classdef DataPlotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AddnewgroupEditField       matlab.ui.control.EditField
        AddnewgroupEditFieldLabel  matlab.ui.control.Label
        DeselectallButton          matlab.ui.control.Button
        SelectallButton            matlab.ui.control.Button
        AvailabledatasetincurrentdataseriesLabel  matlab.ui.control.Label
        DataseriesLabel            matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        UITableSubData             matlab.ui.control.Table
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        function displaySubData(app)
            selection = app.UITable.Selection;

            if numel(selection)==0
                return;
            end

            data=app.Data.data(selection(1)).data;
            
            t=app.Data.data(selection(1)).plotProperties;

            if numel(t)==0
               %t=  app.UITableSubData.Data;
               t={};
               varnames=data.Properties.VariableNames;

               columnformat={[] [] [] [] [] {'id' 'prob' 'labels'}};

                 for i=1:numel(varnames)
                   t{i,1}= false;
                   t{i,2}= varnames{i};
                   t{i,3}= class(data.(varnames{i}));
                   t{i,4}= 'k';
                   t{i,5}= 2;
                    
                   if numel(find(contains(varnames{i},'id')))
                   t{i,6}= 'id';
                   end
                   if numel(find(contains(varnames{i},'prob')))
                   t{i,6}= 'prob';
                   end
                   if numel(find(contains(varnames{i},'labels')))
                   t{i,6}= 'labels';
                   end


                 end
            else
               t=app.Data.data(selection(1)).plotProperties;
               columnformat=app.Data.data(selection(1)).plotGroup;
            end

    
            app.UITableSubData.ColumnFormat=columnformat;
            %app.UITableSubData.columnFormat=columnformat;
            app.UITableSubData.Data=t;

            app.Data.data(selection(1)).plotProperties=t;

            app.Data.data(selection(1)).plotGroup=columnformat;
        end


        function displayData(app)
             
            t=app.UITable.Data;
            data=app.Data.data;

              for i=1:numel(data)

                  if data(i).show
                   t{i,1}= true;
                  else
                   t{i,1}= false;
                  end
                  
                   t{i,2}= data(i).groupid;
                   t{i,3}= data(i).parentid;
                   t{i,4}= char(data(i).class);
                   t{i,5}= char(data(i).type);

              end

            app.UITable.Data=t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, roiobj)
         
            if nargin==1
                roiobj=[];
                data=[];
            end

            if nargin==2
                roiobj=[];
            end
            
            % here
            if numel(data)
            if numel(data(1).data)==0
                data=[];
            end
            end
            
            app.Data.data=data; 
            app.Data.roiobj=roiobj;

            t=app.UITable;
            t.Data=[];
            
           %t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};

            t.ColumnWidth={100, 'auto', 'auto', 'auto','auto'};
            t.ColumnEditable=[true false false false false];

            t=app.UITableSubData;
            t.Data=[];
            
           %t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};

            t.ColumnWidth={100, 'auto', 'auto', 'auto','auto', 100};
            t.ColumnEditable=[true false true true true true];


            displayData(app)

            displaySubData(app)

            for i=1:numel(app.Data.data)
                if app.Data.data(i).show
              %      app.Data.data(i).plot;
                end
            end

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;

            if numel(selection)
            displaySubData(app)
            else
            app.UITableSubData.Data={};
            end
            
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            selection = app.UITable.Selection;

          if numel(selection)==0
              return;
          end

          t=app.UITableSubData.Data;
          t(:,1)={true};
          app.UITableSubData.Data=t;
          app.Data.data(selection(1)).plotProperties=app.UITableSubData.Data;

          h=app.Data.data(selection(1)).plot;
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
             selection = app.UITable.Selection;

          if numel(selection)==0
              return;
          end

          t=app.UITableSubData.Data;
          t(:,1)={false};
          app.UITableSubData.Data=t;
          app.Data.data(selection(1)).plotProperties=app.UITableSubData.Data;

           h=app.Data.data(selection(1)).plot;
        end

        % Cell edit callback: UITableSubData
        function UITableSubDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;


          selection = app.UITable.Selection;

          if numel(selection)==0
              return;
          end

          
          app.Data.data(selection(1)).plotProperties=app.UITableSubData.Data;
        
          displaySubData(app)

          h=app.Data.data(selection(1)).plot; % plot the selected data


        end

        % Value changed function: AddnewgroupEditField
        function AddnewgroupEditFieldValueChanged(app, event)
            value = app.AddnewgroupEditField.Value;
            
             selection = app.UITable.Selection;

          if numel(selection)==0
              return;
          end

  columnformat=app.Data.data(selection(1)).plotGroup{6};

  app.Data.data(selection(1)).plotGroup{6}=[columnformat {value}];
  displaySubData(app);

  app.AddnewgroupEditField.Value='';

            % here : enter new group 

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if newData==1
                h=app.Data.data(indices(1)).plot;
               
                app.Data.data(indices(1)).show=true;
            else
                h=findobj('Tag',app.Data.data(indices(1)).id);
                app.Data.data(indices(1)).show=false;
                delete(h);
                
               %  app.UITable.Selection=[];
            end


            %displaySubData(app);
            UITableSelectionChanged(app, event)

        end

        % Selection changed function: UITableSubData
        function UITableSubDataSelectionChanged(app, event)
            selection = app.UITableSubData.Selection;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITableSubData = uitable(app.UIFigure);
            app.UITableSubData.ColumnName = {'Plot'; 'Name'; 'Type'; 'Color'; 'Width'; 'Plot group'};
            app.UITableSubData.RowName = {};
            app.UITableSubData.ColumnEditable = [true false false true true true];
            app.UITableSubData.CellEditCallback = createCallbackFcn(app, @UITableSubDataCellEdit, true);
            app.UITableSubData.SelectionChangedFcn = createCallbackFcn(app, @UITableSubDataSelectionChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Sel'; 'Name'; 'Parent'; 'Class'; 'Type'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.DataseriesLabel = uilabel(app.UIFigure);
            app.DataseriesLabel.Text = 'Dataseries:';
            app.AvailabledatasetincurrentdataseriesLabel = uilabel(app.UIFigure);
            app.AvailabledatasetincurrentdataseriesLabel.Text = 'Available dataset in current dataseries:';
            app.SelectallButton = uibutton(app.UIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.UIFigure, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.AddnewgroupEditFieldLabel = uilabel(app.UIFigure);
            app.AddnewgroupEditFieldLabel.Text = 'Add new group:';
            app.AddnewgroupEditField = uieditfield(app.UIFigure, 'text');
            app.AddnewgroupEditField.ValueChangedFcn = createCallbackFcn(app, @AddnewgroupEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataPlotGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end