classdef ProcessorImporterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TransferparametersPanel         matlab.ui.container.Panel
        ChooseamonglocallyavailableprocessorsDropDown  matlab.ui.control.DropDown
        ChooseamonglocallyavailableprocessorsDropDownLabel  matlab.ui.control.Label
        OuputprocessorpathnamePanel     matlab.ui.container.Panel
        EnternewprocessornameEditField  matlab.ui.control.EditField
        EnternewprocessornameEditFieldLabel  matlab.ui.control.Label
        SetprocessorpathLabel           matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        ProceedButton                   matlab.ui.control.Button
        ProcessorcreationmethodButtonGroup  matlab.ui.container.ButtonGroup
        DuplicatefromclassifierrepositoryButton  matlab.ui.control.RadioButton
        DuplicateexistingprocessorintheworkspaceButton  matlab.ui.control.RadioButton
        NewprocessorButton              matlab.ui.control.RadioButton
    end


    properties (Access = private)
        callingApp % Description
        Data % Description
        inputProject % Description
    end

    methods (Access = private)
        
        function getAvailableProcess(app,option)
            
            varlist=evalin('base','who');
            st=struct('Project',{''},'Projectprocess',{''});
            cc=0;
            cd=0;
            app.Data={};

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});
                
                st.Project{cc}=varlist{i};

                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    
                    tmpprocess={};

                    for k=1:numel(tmp.processing.processor)
                        %  k
                        tmpprocess = [tmpprocess tmp.processing.processor(k).strid];
                    end

                    st.Projectprocess{cc}=tmpprocess;

                end

            end

          %  st
            app.Data=st;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, shallowObj)
            app.callingApp=callingApp;
            app.inputProject.shallowObj=shallowObj;
             app.TransferparametersPanel.Enable='off';
             
            if numel(app.inputProject.shallowObj)==0
                app.SetprocessorpathLabel.Enable='on';
                app.Button.Enable='on';
                app.ProceedButton.Enable='off';
                app.UIFigure.Name='Creating a new independent processor';
            else
                 app.UIFigure.Name='Creating a project-dependent processor';
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)
        end

        % Selection changed function: 
        % ProcessorcreationmethodButtonGroup
        function ProcessorcreationmethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProcessorcreationmethodButtonGroup.SelectedObject;

            if ~strcmp(selectedButton.Text,'New processor')

                app.TransferparametersPanel.Enable='on';
                % list all avaiable classi in the workspace
                
                getAvailableClassi(app,'workspace')
                s=app.Data;
                
                cc=1;
                displaystr={};
                varstr={};
                
                for i=1:numel(s.Projectprocess)
                    
                    proj=s.Project{i};
                    
                    shallowObj=evalin('base',proj);
                    
                    for k=1:numel(s.Projectprocess{i})
                        varstr{cc}=[proj '.processing.processor(' num2str(k) ')'];
                        
                        classiObj=evalin('base',varstr{cc});
                     
                        
                        displaystr{cc}=[proj '  //  ' s.Projectclassi{i}{k} ];
                       
                        cc=cc+1;
                    end
                end
                
                
                app.ChooseamonglocallyavailableprocessorsDropDown.Items=displaystr;
                app.Data.varstr=varstr; % list of classifier variables in the workspace
                app.Data.displaystr=displaystr;
                
             
            else
                app.TransferparametersPanel.Enable='off';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.inputProject.path=[];
           % filename=app.EnternewprocessornameEditField.Value;

            [filename,path,rep] = uiputfile('*.mat','File Selection',fullfile(pwd,['no_need_to_set_filename_here']));
            if isequal(filename,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected ', fullfile(path, filename)]);
            end
            %         end

            app.inputProject.path=path;
            app.SetprocessorpathLabel.Text=path;
            app.ProceedButton.Enable='on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)

        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

               d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                    'Message','Creating new processor...');
            d.Value=0.1;
            
            if numel(app.inputProject.shallowObj)==0 % independent classifier to be created
                    filename=app.EnternewprocessornameEditField.Value;
                    path=app.inputProject.path;
                    classiObj=processNew('path',path,'filename',filename); % creates and saves object
                    assignin('base',filename,classiObj);

                else % classifier is associated to project 
                    
                    filename=app.EnternewprocessornameEditField.Value;
                    app.inputProject.shallowObj.addProcessor('name',filename);
                    classiObj=app.inputProject.shallowObj.processing.processor(end);
            end


            if app.DuplicateexistingprocessorintheworkspaceButton.Value==1 % classifier transfered from exisiting one
                % gathering classi variable
                vars=app.Data.varstr;
                displaystr=app.Data.displaystr;
                selectedvarstr=app.ChooseamonglocallyavailableprocessorsDropDown.Value;
                ok=0;
                
                for i=1:numel(displaystr)
                    
                if strcmp(displaystr{i},selectedvarstr)
                    ok=1;
                    break
                end
                end
                
                if ok==0
                   uialert(app.DetecDivUIFigure,['Could not find item in the list!'] ,'Warning','Icon','warning');
                end
                selectedvar=evalin('base',vars{i});
                
                
                if numel(app.inputProject.shallowObj)==0
                    %classiObj
                else
                    classiObj=app.inputProject.shallowObj.processing.processor(end);  
                end
                
                d.Value=0.3;
              
            end

             d.Value=0.66;
             d.Message='Saving processor.... Please wait...';
              
            processSave(classiObj);
            
            d.Message='Done !';
            d.Value=1;
            pause(1);
            close(d);
            uiresume(app.callingApp.DetecDivUIFigure);
            
             if app.NewprocessorButton.Value==1 % new classifier from scratch
                 processGUI(classiObj) ; % laucnes classifier GUI 
             end
             
            delete(app)
        end

        % Value changed function: 
        % ChooseamonglocallyavailableprocessorsDropDown
        function ChooseamonglocallyavailableprocessorsDropDownValueChanged(app, event)
            value = app.ChooseamonglocallyavailableprocessorsDropDown.Value;
            pix=find(contains(app.ChooseamonglocallyavailableprocessorsDropDown.Items,value));
            app.EnterROIindicesEditField.Value=['1:' num2str(app.Data.availableROIs(pix))];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProcessorcreationmethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessorcreationmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessorcreationmethodButtonGroupSelectionChanged, true);
            app.ProcessorcreationmethodButtonGroup.Title = 'Processor creation method';
            app.NewprocessorButton = uiradiobutton(app.ProcessorcreationmethodButtonGroup);
            app.NewprocessorButton.Text = 'New processor';
            app.NewprocessorButton.Value = true;
            app.DuplicateexistingprocessorintheworkspaceButton = uiradiobutton(app.ProcessorcreationmethodButtonGroup);
            app.DuplicateexistingprocessorintheworkspaceButton.Text = 'Duplicate existing processor in the workspace';
            app.DuplicatefromclassifierrepositoryButton = uiradiobutton(app.ProcessorcreationmethodButtonGroup);
            app.DuplicatefromclassifierrepositoryButton.Enable = 'off';
            app.DuplicatefromclassifierrepositoryButton.Tooltip = {'Not yet enabled'};
            app.DuplicatefromclassifierrepositoryButton.Text = 'Duplicate from classifier repository';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OuputprocessorpathnamePanel = uipanel(app.UIFigure);
            app.OuputprocessorpathnamePanel.Title = 'Ouput processor path & name';
            app.Button = uibutton(app.OuputprocessorpathnamePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = '...';
            app.SetprocessorpathLabel = uilabel(app.OuputprocessorpathnamePanel);
            app.SetprocessorpathLabel.Enable = 'off';
            app.SetprocessorpathLabel.Tooltip = {'A path is required only when setting up an independent classifier'};
            app.SetprocessorpathLabel.Text = 'Set processor path:';
            app.EnternewprocessornameEditFieldLabel = uilabel(app.OuputprocessorpathnamePanel);
            app.EnternewprocessornameEditFieldLabel.Text = 'Enter new processor name:';
            app.EnternewprocessornameEditField = uieditfield(app.OuputprocessorpathnamePanel, 'text');
            app.EnternewprocessornameEditField.Value = 'my_process';
            app.TransferparametersPanel = uipanel(app.UIFigure);
            app.TransferparametersPanel.Tooltip = {'Specify transfer parameters in case a classifier is transferred to the new classifier'};
            app.TransferparametersPanel.Title = 'Transfer parameters';
            app.ChooseamonglocallyavailableprocessorsDropDownLabel = uilabel(app.TransferparametersPanel);
            app.ChooseamonglocallyavailableprocessorsDropDownLabel.Text = 'Choose among locally available processors:';
            app.ChooseamonglocallyavailableprocessorsDropDown = uidropdown(app.TransferparametersPanel);
            app.ChooseamonglocallyavailableprocessorsDropDown.Items = {'Processor1', ''};
            app.ChooseamonglocallyavailableprocessorsDropDown.Editable = 'on';
            app.ChooseamonglocallyavailableprocessorsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseamonglocallyavailableprocessorsDropDownValueChanged, true);
            app.ChooseamonglocallyavailableprocessorsDropDown.Value = 'Processor1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessorImporterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end