classdef ROIAdjustGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        WarningLabel           matlab.ui.control.Label
        BinningDropDown        matlab.ui.control.DropDown
        BinningDropDownLabel   matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
        ChannelMappingTable_2  matlab.ui.control.Table
        CancelButton           matlab.ui.control.Button
        ProceedButton          matlab.ui.control.Button
        heightEditField        matlab.ui.control.NumericEditField
        heightEditFieldLabel   matlab.ui.control.Label
        widthEditField         matlab.ui.control.NumericEditField
        widthEditFieldLabel    matlab.ui.control.Label
        minYEditField          matlab.ui.control.NumericEditField
        minYEditFieldLabel     matlab.ui.control.Label
        minXEditField          matlab.ui.control.NumericEditField
        minXEditFieldLabel     matlab.ui.control.Label
        ChannelMappingTable    matlab.ui.control.Table
        UITable                matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data struct = struct()      % structure style roiImporterGUI.Data
        ChannelKeep   cell
        DataKeep      cell
        Poly                     % handle du drawpolygon
        IsUpdatingFields logical = false  % pour éviter les boucles callback
    Rect                    % handle du drawrectangle
    ROISettings   cell      % par ligne de UITable : struct('bbox',[x y w h], 'binning',factor)
    SizeMismatch  logical   % vecteur logique par ligne (ROIs de tailles différentes ?)
    LastRowSelection double = NaN;   % pour sauver les réglages de la ligne précédente
    end
    
    methods (Access = private)
        
       
    function localCloseProgress(app, dlg)
        try
            if ~isempty(dlg) && isvalid(dlg)
                close(dlg);
            end
        catch
        end
    end


           function tf = hasValidROIs(app,obj)
            tf = false;
            if ~isprop(obj,'roi') || isempty(obj.roi)
                return;
            end
            r = obj.roi(1);
            if ~isa(r,'roi') || isempty(r.id)
                return;
            end
            tf = true;
           end

         function displayROIs(app, option)

            s         = app.Data.st;
            mainApp   = app.Data.mainApp;
            cc        = 1;
            displaystr2 = {};
            varstr      = {};
            target      = {};

            % --- Parcours des projets (shallow) ---
            for i = 1:numel(s.Project)
                proj = s.Project{i};
                shallowObj = evalin('base', proj); %#ok<NASGU>

                % FOV
                if ~isempty(s.Projectpos)
                    for k = 1:numel(s.Projectpos{i})
                        tmp = evalin('base', sprintf('%s.fov(%d)', proj, k));
                        if hasValidROIs(app,tmp)
                            varstr{cc}      = sprintf('%s.fov(%d)', proj, k);
                            displaystr2{cc,1} = proj;
                            displaystr2{cc,2} = s.Projectpos{i}{k};
                            displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                            target{cc}      = tmp;
                            % après avoir mis target{cc} = tmp;
rois = tmp.roi(:);
w = arrayfun(@(r) r.value(3), rois);
h = arrayfun(@(r) r.value(4), rois);
app.SizeMismatch(cc,1) = (numel(unique(w)) > 1 || numel(unique(h)) > 1);

                            cc = cc + 1;
                        end
                    end
                end

                % Classifications rattachées au projet
                if ~isempty(s.Projectclassi)
                    for k = 1:numel(s.Projectclassi{i})
                        val = k;
                        if nargin == 3 && ~isempty(option)
                            mp = evalin('base', proj);
                            for l = 1:numel(mp.processing.classification)
                                if strcmp(mp.processing.classification(l).strid, option)
                                    val = l;
                                    break
                                end
                            end
                        end
                        tmp = evalin('base', sprintf('%s.processing.classification(%d)', proj, val));
                        if hasValidROIs(app,tmp)
                            varstr{cc}      = sprintf('%s.processing.classification(%d)', proj, val);
                            displaystr2{cc,1} = proj;
                            displaystr2{cc,2} = s.Projectclassi{i}{k};
                            displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                            target{cc}      = tmp;
                            % après avoir mis target{cc} = tmp;
rois = tmp.roi(:);
w = arrayfun(@(r) r.value(3), rois);
h = arrayfun(@(r) r.value(4), rois);
app.SizeMismatch(cc,1) = (numel(unique(w)) > 1 || numel(unique(h)) > 1);
                            cc = cc + 1;
                        end
                    end
                end
            end

            % --- Parcours des classi standalone (comme roiImporterGUI) ---
            for i = 1:numel(s.Classifier)
                clas = s.Classifier{i};
                if isempty(clas)
                    continue;
                end
                if isstring(clas)
                    clas = clas(1);
                    clas = char(clas);
                end
                if ~ischar(clas)
                    warning('ROIAdjustGUI:ClassifierType',...
                        'Entrée st.Classifier{%d} non textuelle, ignorée.', i);
                    continue;
                end

                try
                    tmp = evalin('base', clas);
                catch
                    warning('ROIAdjustGUI:EvalinFailed',...
                        'Impossible de trouver la variable "%s" dans le workspace.', clas);
                    continue;
                end

                if hasValidROIs(app,tmp)
                    varstr{cc}      = clas;
                    displaystr2{cc,1} = '';
                    displaystr2{cc,2} = clas;
                    displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                    target{cc}      = tmp;
                    % après avoir mis target{cc} = tmp;
rois = tmp.roi(:);
w = arrayfun(@(r) r.value(3), rois);
h = arrayfun(@(r) r.value(4), rois);
app.SizeMismatch(cc,1) = (numel(unique(w)) > 1 || numel(unique(h)) > 1);
                    cc = cc + 1;
                end
            end

            app.Data.varstr      = varstr;
            app.Data.displaystr2 = displaystr2;
            app.Data.target      = target;

           nRows = size(displaystr2,1);

t = app.UITable;
t.Data = [];

t.ColumnWidth    = {50, 250, 200, 'auto'};
t.ColumnEditable = [true false false true];

d = cell(nRows,4);
for i = 1:nRows
    d{i,1} = false;

    % Optionnel : pré-sélectionner la première si option fourni
    if exist('option','var') && ~isempty(option) && i==1
        d{i,1} = true;
    end

    d{i,2} = displaystr2{i,1};
    d{i,3} = displaystr2{i,2};
    d{i,4} = displaystr2{i,3};
end

t.Data = d;


app.Data.target      = target;
app.Data.varstr      = varstr;
app.Data.displaystr2 = displaystr2;

% init des settings par ligne
nRows = numel(target);
app.ROISettings = cell(nRows,1);
if isempty(app.SizeMismatch)
    app.SizeMismatch = false(nRows,1);
end
        

            % Si on a au moins une ligne, on la sélectionne par défaut
            if nRows > 0
                app.UITable.Selection = [1 1];
                UITableSelectionChanged(app, struct());
            end
         end

         function updateWarningLabel(app, row)
    if isempty(app.SizeMismatch) || row > numel(app.SizeMismatch)
        app.WarningLabel.Text = '';
        return;
    end

    if app.SizeMismatch(row)
        app.WarningLabel.Text = 'Warning: ROIs for this item have different sizes; batch adjustment will be harmonized.';
    else
        app.WarningLabel.Text = '';
    end
end

function saveCurrentRowSettings(app)
    if isnan(app.LastRowSelection)
        return;
    end
    row = app.LastRowSelection;
    if row < 1 || row > numel(app.ROISettings)
        return;
    end

    % Binning actuel
    bin = str2double(app.BinningDropDown.Value);
    if isnan(bin) || bin <= 0
        bin = 1;
    end

    % bbox en full-res : on lit les editfields (déjà en entiers)
    x = round(app.minXEditField.Value);
    y = round(app.minYEditField.Value);
    w = round(app.widthEditField.Value);
    h = round(app.heightEditField.Value);

    app.ROISettings{row} = struct('bbox',[x y w h], 'binning',bin);
end


        %--------------------------------------------------------------
        % Mise à jour des tables Channels & Dataseries pour la ligne sélectionnée
        %--------------------------------------------------------------

        function updateChannelAndDataTables(app)
    sel = app.UITable.Selection;
    if isempty(sel)
        app.ChannelMappingTable.Data   = {};
        app.ChannelMappingTable_2.Data = {};
        return;
    end
    row = sel(1,1);

    tgt  = app.Data.target{row};
    if isempty(tgt), return; end
    rois = tgt.roi(:);

    if isempty(rois)
        app.ChannelMappingTable.Data   = {};
        app.ChannelMappingTable_2.Data = {};
        return;
    end

    % -----------------------
    % 🔹 CHANNELS
    % -----------------------
    allChannels = {};
    for r = 1:numel(rois)
        ch = rois(r).display.channel;
        if ischar(ch), ch = {ch}; end
        allChannels = [allChannels, ch]; %#ok<AGROW>
    end
    chNames = unique(allChannels,'stable');
    nCh = numel(chNames);

    % récupère état précédent si existant
    oldCh = {};
    if numel(app.ChannelKeep) >= row
        oldCh = app.ChannelKeep{row};
    end

    dataCh = cell(nCh,2);
    for i = 1:nCh
        chName = chNames{i};

        % valeur par défaut = keep
        keep = true;
        if ~isempty(oldCh) && isfield(oldCh,'name')
            idx = find(strcmp({oldCh.name}, chName),1);
            if ~isempty(idx)
                keep = oldCh(idx).keep;
            end
        end

        dataCh{i,1} = keep;
        dataCh{i,2} = chName;
    end

    app.ChannelMappingTable.Data = dataCh;
    app.ChannelMappingTable.ColumnEditable = [true false];


    % -----------------------
    % 🔹 DATASERIES
    % -----------------------
    allDS = {};
    for r = 1:numel(rois)
        d = rois(r).data;
        if isempty(d), continue; end
        for k = 1:numel(d)
            if isprop(d(k),'groupid') && ~isempty(d(k).groupid)
                allDS{end+1} = char(d(k).groupid); %#ok<AGROW>
            end
        end
    end
    dsNames = unique(allDS,'stable');
    nDS = numel(dsNames);

    oldDS = {};
    if numel(app.DataKeep) >= row
        oldDS = app.DataKeep{row};
    end

    dataDS = cell(nDS,2);
    for i = 1:nDS
        name = dsNames{i};
        keep = true;

        if ~isempty(oldDS) && isfield(oldDS,'name')
            idx = find(strcmp({oldDS.name}, name),1);
            if ~isempty(idx)
                keep = oldDS(idx).keep;
            end
        end

        dataDS{i,1} = keep;
        dataDS{i,2} = name;
    end

    app.ChannelMappingTable_2.Data = dataDS;
    app.ChannelMappingTable_2.ColumnEditable = [true false];
end


        %--------------------------------------------------------------
        % Aperçu ROI et polygon
        %--------------------------------------------------------------
    function updatePolygonPreview(app)
    sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);

    if row > numel(app.Data.target) || isempty(app.Data.target{row})
        return;
    end

    tgt  = app.Data.target{row};
    rois = tgt.roi(:);
    if isempty(rois), return; end

    % ROIs sélectionnées dans la colonne 4
    raw = app.UITable.Data{row,4};
    roisIdx = parseROIString(app, raw, numel(rois));
    if isempty(roisIdx)
        roisIdx = 1;
    end
    refIdx = roisIdx(1);
    r = rois(refIdx);

    % image full-res de référence
    imFull = getROIFramePreview(app, r);
    [hFull, wFull, ~] = size(imFull);

    % récupère / initialise settings pour cette ligne
    st = app.ROISettings{row};
    if isempty(st) || ~isfield(st,'bbox') || isempty(st.bbox)
        bboxFull = [1 1 wFull hFull];
        bin = 1;
    else
        bboxFull = st.bbox;
        if isfield(st,'binning') && ~isempty(st.binning)
            bin = st.binning;
        else
            bin = 1;
        end
    end

    % s'assure que les valeurs sont entières
    bboxFull = round(bboxFull);
    bin = max(1, round(bin));

    % synchonise dropdown binning
    if any(strcmp(app.BinningDropDown.Items, num2str(bin)))
        app.BinningDropDown.Value = num2str(bin);
    else
        app.BinningDropDown.Value = app.BinningDropDown.Items{1};
        bin = str2double(app.BinningDropDown.Value);
    end

    % applique le binning pour l'affichage
    imDisp = applyBinning(app, imFull, bin);

    cla(app.UIAxes);
    imagesc(app.UIAxes, imDisp);
    colormap(app.UIAxes, gray);
    axis(app.UIAxes,'image');
    app.UIAxes.YDir = 'reverse';
    hold(app.UIAxes,'on');
    title(app.UIAxes, sprintf('ROI preview (idx %d)', refIdx));

    % bbox full-res -> coords sur image binned
    xF = bboxFull(1);  yF = bboxFull(2);
    wF = bboxFull(3);  hF = bboxFull(4);

    xD = xF / bin;
    yD = yF / bin;
    wD = wF / bin;
    hD = hF / bin;

    rectPosDisp = [xD yD wD hD];

    % supprime ancien rectangle
    if ~isempty(app.Rect) && isvalid(app.Rect)
        delete(app.Rect);
    end

    app.Rect = drawrectangle(app.UIAxes, ...
        'Position', rectPosDisp, ...
        'FaceAlpha', 0.1);

    % listeners : quand on bouge le rectangle, on met à jour les champs
    addlistener(app.Rect,'MovingROI',@(src,evt) app.syncFieldsFromRect());
    addlistener(app.Rect,'ROIMoved', @(src,evt) app.syncFieldsFromRect());

    % met à jour les champs (en full-res)
    app.minXEditField.Value   = xF;
    app.minYEditField.Value   = yF;
    app.widthEditField.Value  = wF;
    app.heightEditField.Value = hF;

    % mémorise
    app.ROISettings{row} = struct('bbox',[xF yF wF hF],'binning',bin);
end


        function syncFieldsFromPolygon(app)
            if isempty(app.Poly) || ~isvalid(app.Poly)
                return;
            end
            pos = app.Poly.Position;
            minX = min(pos(:,1));
            maxX = max(pos(:,1));
            minY = min(pos(:,2));
            maxY = max(pos(:,2));
            w = maxX - minX;
            h = maxY - minY;

            app.IsUpdatingFields = true;
            app.minXEditField.Value   = minX;
            app.minYEditField.Value   = minY;
            app.widthEditField.Value  = w;
            app.heightEditField.Value = h;
            app.IsUpdatingFields      = false;
        end

        function syncFieldsFromRect(app)
    if isempty(app.Rect) || ~isvalid(app.Rect)
        return;
    end

    sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);

    % bin pour cette ligne
    st = app.ROISettings{row};
    if isempty(st) || ~isfield(st,'binning') || isempty(st.binning)
        bin = str2double(app.BinningDropDown.Value);
        if isnan(bin) || bin <= 0, bin = 1; end
    else
        bin = st.binning;
    end

    posDisp = app.Rect.Position;  % [xD yD wD hD] en coords binned
    xF = round(posDisp(1) * bin);
    yF = round(posDisp(2) * bin);
    wF = round(posDisp(3) * bin);
    hF = round(posDisp(4) * bin);

    % maj des champs (entiers)
    app.minXEditField.Value   = xF;
    app.minYEditField.Value   = yF;
    app.widthEditField.Value  = wF;
    app.heightEditField.Value = hF;

    % maj settings
    app.ROISettings{row} = struct('bbox',[xF yF wF hF], 'binning',bin);
end

function syncRectFromFields(app)
    sel = app.UITable.Selection;
    if isempty(sel) || isempty(app.Rect) || ~isvalid(app.Rect)
        return;
    end
    row = sel(1,1);

    xF = round(app.minXEditField.Value);
    yF = round(app.minYEditField.Value);
    wF = round(app.widthEditField.Value);
    hF = round(app.heightEditField.Value);

    st = app.ROISettings{row};
    if isempty(st) || ~isfield(st,'binning') || isempty(st.binning)
        bin = str2double(app.BinningDropDown.Value);
        if isnan(bin) || bin <= 0, bin = 1; end
    else
        bin = st.binning;
    end

    xD = xF / bin;
    yD = yF / bin;
    wD = wF / bin;
    hD = hF / bin;

    app.Rect.Position = [xD yD wD hD];

    app.ROISettings{row} = struct('bbox',[xF yF wF hF], 'binning',bin);
end


        function syncPolygonFromFields(app, whichField)
            if app.IsUpdatingFields, return; end
            if isempty(app.Poly) || ~isvalid(app.Poly)
                return;
            end

            x  = app.minXEditField.Value;
            y  = app.minYEditField.Value;
            w  = max(1, app.widthEditField.Value);
            h  = max(1, app.heightEditField.Value);

            % Si on modifie width/height, on garde le centre de la forme
            if any(strcmp(whichField, {'width','height'}))
                pos = app.Poly.Position;
                minX = min(pos(:,1)); maxX = max(pos(:,1));
                minY = min(pos(:,2)); maxY = max(pos(:,2));
                cx = (minX + maxX)/2;
                cy = (minY + maxY)/2;
                x  = cx - w/2;
                y  = cy - h/2;
                app.minXEditField.Value = x;
                app.minYEditField.Value = y;
            end

            polyPos = [x y; x+w y; x+w y+h; x y+h];

            app.IsUpdatingFields = true;
            app.Poly.Position = polyPos;
            app.IsUpdatingFields = false;
        end

        function roisIdx = parseROIString(app,str, nMax)
            % interprète le contenu de la colonne "Select ROIs"
            if isnumeric(str)
                roisIdx = str;
                roisIdx = roisIdx(roisIdx>=1 & roisIdx<=nMax);
                return;
            end
            s = strtrim(char(str));
            if isempty(s)
                roisIdx = 1:nMax;
                return;
            end
            s = regexprep(s,'[,;]',' ');
            s = regexprep(s,'\s+',' ');
            try
                roisIdx = str2num(s); %#ok<ST2NM>
            catch
                roisIdx = [];
            end
            roisIdx = unique(roisIdx);
            roisIdx = roisIdx(roisIdx>=1 & roisIdx<=nMax);
        end


        function im = getROIFramePreview(~, r)
    % Assure que l'image de la ROI est chargée
    if isempty(r.image)
        try
            r.load();      % ta méthode existante, sans argument
        catch ME
            warning('ROIAdjustGUI:getROIFramePreview',...
                'Impossible de charger l''image pour ROI "%s": %s', r.id, ME.message);
        end
    end

    if ~isempty(r.image)
        % r.image : H x W x C x T
        im = r.image(:,:,1,1);      % channel 1, frame 1
    else
        % fallback si vraiment rien
        im = zeros(64,64,'uint8');
    end

    % On s'assure d’avoir 2D pour imagesc
    if ndims(im) > 2
        im = im(:,:,1);
    end
        end


function imDisp = applyBinning(~, imFull, bin)
    if bin <= 1
        imDisp = imFull;
    else
        imDisp = imresize(imFull, 1/bin);
    end
end

function proceed(app)

    dataTable = app.UITable.Data;
    if isempty(dataTable)
        uialert(app.UIFigure,'No ROI source available','Error');
        return;
    end

    selRows = find(cell2mat(dataTable(:,1)));
    if isempty(selRows)
        uialert(app.UIFigure,'No ROI source selected','Error');
        return;
    end

    % Sauvegarde réglages ligne courante
    saveCurrentRowSettings(app);

    % ---------- pré-scan ----------
    totalROI = 0;
    for idxRow = selRows'
        if idxRow > numel(app.Data.target) || isempty(app.Data.target{idxRow})
            continue;
        end
        tgt  = app.Data.target{idxRow};
        rois = tgt.roi(:);
        nROI = numel(rois);
        if nROI == 0, continue; end

        rawROI = dataTable{idxRow,4};
        roiIdx = parseROIString(app, rawROI, nROI);
        if isempty(roiIdx), roiIdx = 1:nROI; end

        totalROI = totalROI + numel(roiIdx);
    end

    if totalROI == 0
        uialert(app.UIFigure,'No ROI to process (empty selections).','Error');
        return;
    end

    % ---------- confirmation ----------
    msg = sprintf(['You are about to modify %d ROI(s) across %d selected source(s).\n\n' ...
                   'This will apply adjustROIAdvanced() and overwrite ROI geometry/channels/dataseries.\n\n' ...
                   'Do you want to continue?'], totalROI, numel(selRows));

    choice = uiconfirm(app.UIFigure, msg, 'Confirm ROI modification', ...
        'Options', {'Proceed','Cancel'}, ...
        'DefaultOption', 2, ...
        'CancelOption', 2);

    fprintf('[ROIAdjust] choice = %s\n', choice);

    if ~strcmp(choice,'Proceed')
        return;
    end

    % ---------- progressdlg ----------
    d = uiprogressdlg(app.UIFigure, ...
        'Title','Please Wait...', ...
        'Message','Adjusting ROIs...', ...
        'Indeterminate','off', ...
        'Cancelable','on');
    d.Value = 0;

    roiCount = 0;

    try
        for idxRow = selRows'

            if d.CancelRequested
                close(d);
                uialert(app.UIFigure, sprintf('Cancelled: %d/%d ROI(s) processed.', roiCount, totalROI), 'Cancelled');
                return;
            end

            if idxRow > numel(app.Data.target) || isempty(app.Data.target{idxRow})
                continue;
            end

            tgt  = app.Data.target{idxRow};
            rois = tgt.roi(:);
            nROI = numel(rois);
            if nROI == 0
                continue;
            end

            fprintf('[ROIAdjust] row=%d expr=%s nROI=%d\n', idxRow, app.Data.varstr{idxRow}, nROI);

            rawROI = dataTable{idxRow,4};
            roiIdx = parseROIString(app, rawROI, nROI);
            if isempty(roiIdx), roiIdx = 1:nROI; end

            % ---- bbox + binning (bbox = LOCAL coords in ROI patch) ----
            bboxLocal = [];
            bin  = [];

            if ~isempty(app.ROISettings) && idxRow <= numel(app.ROISettings) ...
                    && ~isempty(app.ROISettings{idxRow})
                st = app.ROISettings{idxRow};
                if isfield(st,'bbox') && ~isempty(st.bbox)
                    bboxLocal = round(st.bbox);
                end
                if isfield(st,'binning') && ~isempty(st.binning)
                    bin = st.binning;
                end
            end

            if isempty(bboxLocal)
                xL = round(app.minXEditField.Value);
                yL = round(app.minYEditField.Value);
                wL = round(app.widthEditField.Value);
                hL = round(app.heightEditField.Value);
                bboxLocal = [xL yL wL hL];
            end
            bboxLocal(3:4) = max(bboxLocal(3:4), 1);

            % Taille cible commune
            wT = bboxLocal(3);
            hT = bboxLocal(4);

            % ---- ROI modèle = première ROI sélectionnée ----
            modelIdx = roiIdx(1);
            r0  = rois(modelIdx);
            b0  = r0.value(1:4);         % bbox FOV du modèle (peut servir à debug)
            w0  = b0(3);
            h0  = b0(4);

            % On interprète bboxLocal(1:2) comme coords LOCALES dans le patch du modèle
            xRel0 = bboxLocal(1);
            yRel0 = bboxLocal(2);

            % Décalage de centre local demandé (dans repère ROI patch)
            % centre initial local du modèle = [w0/2, h0/2]
            % centre cible local = [xRel0 + wT/2, yRel0 + hT/2]
            dCx = (xRel0 + wT/2) - (w0/2);
            dCy = (yRel0 + hT/2) - (h0/2);

            % ---- Channels / Dataseries ----
            keepChNames = {};
            if ~isempty(app.ChannelKeep) && idxRow <= numel(app.ChannelKeep) ...
                    && ~isempty(app.ChannelKeep{idxRow})
                mapCh = app.ChannelKeep{idxRow};
                maskKeep    = [mapCh.keep];
                keepChNames = {mapCh(maskKeep).name};
                keepChNames = keepChNames(~cellfun(@isempty, keepChNames));
                keepChNames = unique(keepChNames,'stable');
            end

            keepDSNames = {};
            if ~isempty(app.DataKeep) && idxRow <= numel(app.DataKeep) ...
                    && ~isempty(app.DataKeep{idxRow})
                mapDS = app.DataKeep{idxRow};
                maskKeep    = [mapDS.keep];
                keepDSNames = {mapDS(maskKeep).name};
                keepDSNames = keepDSNames(~cellfun(@isempty, keepDSNames));
                keepDSNames = unique(keepDSNames,'stable');
            end

            % ---- apply ROI par ROI ----
            for rIdx = roiIdx

                if d.CancelRequested
                    close(d);
                    uialert(app.UIFigure, sprintf('Cancelled: %d/%d ROI(s) processed.', roiCount, totalROI), 'Cancelled');
                    return;
                end

                roiCount = roiCount + 1;

                d.Value = min(roiCount / totalROI, 1);
                d.Message = sprintf('Adjusting ROI %d/%d (row %d)', roiCount, totalROI, idxRow);
                drawnow limitrate;

                rr = rois(rIdx);

                % bbox FOV actuelle (pour calculer centre local)
                bi = rr.value(1:4);
                wi = bi(3);
                hi = bi(4);

                % centre local initial = [wi/2, hi/2]
                % centre local cible = [wi/2 + dCx, hi/2 + dCy]
                % top-left local cible :
                xRel = (wi/2 + dCx) - wT/2;
                yRel = (hi/2 + dCy) - hT/2;

                xRel = round(xRel);
                yRel = round(yRel);

                % clamp minimal dans le patch (1-based). On laisse adjustROISize re-clamp aussi.
                xRel = max(1, xRel);
                yRel = max(1, yRel);

                bboxToApply = [xRel yRel wT hT];

                % args
                args = {'bbox', bboxToApply, 'localCrop', true};
                if ~isempty(keepChNames), args = [args, {'keepChannels', keepChNames}]; end
                if ~isempty(keepDSNames), args = [args, {'keepDataseries', keepDSNames}]; end
                if ~isempty(bin),        args = [args, {'binning', bin}]; end

                % log before/after + save
                before = rr.value(1:4);
                rr.adjustROIAdvanced(args{:});
                after  = rr.value(1:4);
                fprintf('  ROI %d: [%g %g %g %g] -> [%g %g %g %g]\n', rIdx, before, after);

                rr.save();
            end
        end

        % ---------- fin succès ----------
        close(d);
        uialert(app.UIFigure,'ROIs adjusted successfully','Success','Icon','success');

        delete(app);

    catch ME
        try, close(d); catch, end
        uialert(app.UIFigure, sprintf('Error during processing:\n%s', ME.message), 'Error');
        rethrow(ME);
    end
end


end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Démarrage standalone :
    %   ROIAdjustGUI()

    app.Data.mainApp = [];              % pas de calling app à ce stade
    app.UIFigure.Name = 'Batch ROI adjust';

    % Récupère les projets / classi / etc. dans le workspace
    s = gatherVariablesFromWorkspace;
    app.Data.st = s;

    app.Data.target      = {};  % objets (classi, shallow.fov, etc.)
    app.Data.varstr      = {};  % expressions dans base workspace
    app.Data.displaystr2 = {};  % [Project, Classifier/FOV, ROIs string]

    % Remplit la UITable avec les objets détectés
    displayROIs(app, []);

    if ~isempty(app.UITable.Data)
    app.UITable.Selection = [1 1];
    UITableSelectionChanged(app, struct());
end


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;

    % Si on édite la colonne "Select ROIs" (4), on force la case "Select" à true
    if indices(1,2) == 4
        pix = indices(1,1);
        app.UITable.Data{pix,1} = true;
    end

    % Met la sélection sur la ligne/cellule éditée
    app.UITable.Selection = indices;

    % Rejoue le pipeline : update tables + affichage ROI
    UITableSelectionChanged(app, event);
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
           % Sauvegarde des settings de la ligne précédente (si existante)
    saveCurrentRowSettings(app);

    sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);
    app.LastRowSelection = row;

    % update tables (channels/datas) si tu en as une
    updateChannelAndDataTables(app);

    % warning éventuel
    updateWarningLabel(app, row);

    % aperçu ROI
    updatePolygonPreview(app);
        end

        % Cell edit callback: ChannelMappingTable
        function ChannelMappingTableCellEdit(app, event)
           data = app.ChannelMappingTable.Data;
    sel  = app.UITable.Selection;
    if isempty(sel) || isempty(data)
        return;
    end

    row = sel(1,1);   % ligne de la UITable (source ROI/classi)

    nCh = size(data,1);
    if nCh == 0
        return;
    end

    % map = struct array avec champs 'name' et 'keep'
    map(1,nCh) = struct('name','', 'keep', true);

    for i = 1:nCh
        % Colonne 1 : Keep (logique)
        keepVal = true;
        if ~isempty(data{i,1})
            keepVal = logical(data{i,1});
        end

        % Colonne 2 : Source Channel Name (string)
        nameVal = '';
        if size(data,2) >= 2 && ~isempty(data{i,2})
            nameVal = char(data{i,2});
        end

        map(i).keep = keepVal;
        map(i).name = nameVal;
    end

    % On stocke ce mapping par ligne de UITable
    app.ChannelKeep{row} = map;
        end

        % Selection changed function: ChannelMappingTable
        function ChannelMappingTableSelectionChanged(app, event)
            
        end

        % Cell edit callback: ChannelMappingTable_2
        function ChannelMappingTable_2CellEdit(app, event)

    data = app.ChannelMappingTable_2.Data;
    sel  = app.UITable.Selection;
    if isempty(sel) || isempty(data)
        return;
    end

    row = sel(1,1);   % ligne de la UITable (source ROI/classi)

    nDS = size(data,1);
    if nDS == 0
        return;
    end

    % map = struct array avec champs 'name' et 'keep'
    map(1,nDS) = struct('name','', 'keep', true);

    for i = 1:nDS
        % Colonne 1 : Keep (logique)
        keepVal = true;
        if ~isempty(data{i,1})
            keepVal = logical(data{i,1});
        end

        % Colonne 2 : Source Dataseries Name
        nameVal = '';
        if size(data,2) >= 2 && ~isempty(data{i,2})
            nameVal = char(data{i,2});
        end

        map(i).keep = keepVal;
        map(i).name = nameVal;
    end

    % On stocke ce mapping par ligne de UITable
    app.DataKeep{row} = map;


        end

        % Selection changed function: ChannelMappingTable_2
        function ChannelMappingTable_2SelectionChanged(app, event)
         
        end

        % Value changed function: minXEditField
        function minXEditFieldValueChanged(app, event)
            app.minXEditField.Value = round(app.minXEditField.Value);
    syncRectFromFields(app);
        end

        % Value changed function: minYEditField
        function minYEditFieldValueChanged(app, event)
          app.minYEditField.Value = round(app.minYEditField.Value);
    syncRectFromFields(app);
        end

        % Value changed function: widthEditField
        function widthEditFieldValueChanged(app, event)
          app.widthEditField.Value = round(app.widthEditField.Value);
    syncRectFromFields(app);
        end

        % Value changed function: heightEditField
        function heightEditFieldValueChanged(app, event)
       app.heightEditField.Value = round(app.heightEditField.Value);
    syncRectFromFields(app);
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

             proceed(app);

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
                     delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
              sel = app.UITable.Selection;
            if isempty(sel)
                return;
            end
            row = sel(1,1);
            if numel(app.Data.target) < row || isempty(app.Data.target{row})
                return;
            end
            tgt  = app.Data.target{row};
            rois = tgt.roi(:);
            if isempty(rois), return; end

            raw = app.UITable.Data{row,4};
            idx = parseROIString(app,raw, numel(rois));
            if isempty(idx)
                idx = 1:numel(rois);
            end

            % centre = celui de la première ROI
            r0 = rois(idx(1));
            val0 = r0.value(:)';
            cx = val0(1) + val0(3)/2;
            cy = val0(2) + val0(4)/2;

            % largeur / hauteur max
            widths  = []; heights = [];
            for k = idx(:)'
                rv = rois(k).value(:)';
                widths(end+1)  = rv(3); %#ok<AGROW>
                heights(end+1) = rv(4); %#ok<AGROW>
            end
            w = max(widths);
            h = max(heights);

            x = cx - w/2;
            y = cy - h/2;

            app.IsUpdatingFields = true;
            app.minXEditField.Value   = x;
            app.minYEditField.Value   = y;
            app.widthEditField.Value  = w;
            app.heightEditField.Value = h;
            app.IsUpdatingFields      = false;

            syncRectFromFields(app);
            saveCurrentRowSettings(app);
        end

        % Value changed function: BinningDropDown
        function BinningDropDownValueChanged(app, event)
               sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);

    bin = str2double(app.BinningDropDown.Value);
    if isnan(bin) || bin <= 0
        bin = 1;
    end

    % garde bbox existante si dispo
    st = app.ROISettings{row};
    if isempty(st) || ~isfield(st,'bbox') || isempty(st.bbox)
        bbox = [app.minXEditField.Value, app.minYEditField.Value,...
                app.widthEditField.Value, app.heightEditField.Value];
    else
        bbox = st.bbox;
    end

    app.ROISettings{row} = struct('bbox',round(bbox), 'binning',bin);

    % réaffiche l'image avec le nouveau binning
    updatePolygonPreview(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select'; 'Project'; 'Classifier or Position (FOV)'; 'Select ROIs'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.ChannelMappingTable = uitable(app.UIFigure);
            app.ChannelMappingTable.ColumnName = {'Keep channel'; 'Source Channel Name'};
            app.ChannelMappingTable.RowName = {};
            app.ChannelMappingTable.ColumnEditable = [true false];
            app.ChannelMappingTable.CellEditCallback = createCallbackFcn(app, @ChannelMappingTableCellEdit, true);
            app.ChannelMappingTable.SelectionChangedFcn = createCallbackFcn(app, @ChannelMappingTableSelectionChanged, true);
            app.minXEditFieldLabel = uilabel(app.UIFigure);
            app.minXEditFieldLabel.Text = 'minX';
            app.minXEditField = uieditfield(app.UIFigure, 'numeric');
            app.minXEditField.ValueChangedFcn = createCallbackFcn(app, @minXEditFieldValueChanged, true);
            app.minYEditFieldLabel = uilabel(app.UIFigure);
            app.minYEditFieldLabel.Text = 'minY';
            app.minYEditField = uieditfield(app.UIFigure, 'numeric');
            app.minYEditField.ValueChangedFcn = createCallbackFcn(app, @minYEditFieldValueChanged, true);
            app.widthEditFieldLabel = uilabel(app.UIFigure);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.UIFigure, 'numeric');
            app.widthEditField.ValueChangedFcn = createCallbackFcn(app, @widthEditFieldValueChanged, true);
            app.heightEditFieldLabel = uilabel(app.UIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightEditField.ValueChangedFcn = createCallbackFcn(app, @heightEditFieldValueChanged, true);
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChannelMappingTable_2 = uitable(app.UIFigure);
            app.ChannelMappingTable_2.ColumnName = {'Keep data'; 'Source Dataseries Name'};
            app.ChannelMappingTable_2.RowName = {};
            app.ChannelMappingTable_2.ColumnEditable = [true false];
            app.ChannelMappingTable_2.CellEditCallback = createCallbackFcn(app, @ChannelMappingTable_2CellEdit, true);
            app.ChannelMappingTable_2.SelectionChangedFcn = createCallbackFcn(app, @ChannelMappingTable_2SelectionChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BinningDropDownLabel = uilabel(app.UIFigure);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.UIFigure);
            app.BinningDropDown.Items = {'1', '2', '4', '16'};
            app.BinningDropDown.ValueChangedFcn = createCallbackFcn(app, @BinningDropDownValueChanged, true);
            app.BinningDropDown.Value = '1';
            app.WarningLabel = uilabel(app.UIFigure);
            app.WarningLabel.Text = 'Warning';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIAdjustGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end