classdef ROIclassiMismatchGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RemoveRuleButton               matlab.ui.control.Button
        AddRuleButton                  matlab.ui.control.Button
        UIEventTable                   matlab.ui.control.Table
        DisplayMaxtrajEditField        matlab.ui.control.NumericEditField
        DisplayMaxtrajEditFieldLabel   matlab.ui.control.Label
        NumberscolormapEditField       matlab.ui.control.EditField
        LabelcolorbarEditField_2Label  matlab.ui.control.Label
        LabelcolormapEditField         matlab.ui.control.EditField
        LabelcolorbarEditFieldLabel    matlab.ui.control.Label
        ShowcolorbarCheckBox           matlab.ui.control.CheckBox
        TrajframewidthEditField        matlab.ui.control.NumericEditField
        TrajframewidthEditFieldLabel   matlab.ui.control.Label
        TrajheightEditField            matlab.ui.control.NumericEditField
        TrajheightEditFieldLabel       matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ProceedButton                  matlab.ui.control.Button
        UIDataseriesTable              matlab.ui.control.Table
        UIROITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        Data struct = struct()                 % like ROIAdjustGUI.Data (st,target,varstr,displaystr2)
        DSSettings cell = {}                   % per UIROITable row: containers.Map(key -> struct('display',..,'compare',..))
        LastROIRow double = NaN                % previous selected row (for saving/restoring)
        IsUpdating logical = false             % avoid callback loops
        DSCache cell = {}   % per UIROITable row: struct('rows',{...},'dsKeys',string(...))
        CurrentEventKey char = ''   % clé (ds|var) actuellement sélectionnée dans la table dataseries


        CurrentDSKeys string = string.empty    % keys aligned with UIDataseriesTable.Data rows
   % Description
    end
    
    methods (Access = private)
        
     function enforceSingleSelectedRow_(app, keepRow)
% Keep only one selected group (col 1 checkbox true), uncheck all others.
    d = app.UIROITable.Data;
    if isempty(d), return; end

    n = size(d,1);
    if keepRow < 1 || keepRow > n, return; end

    app.IsUpdating = true;

    % Ensure keepRow is selected
    try
        d{keepRow,1} = true;
    catch
    end

    % Uncheck others
    for r = 1:n
        if r == keepRow, continue; end
        try
            d{r,1} = false;
        catch
        end
    end

    app.UIROITable.Data = d;

    % (optionnel) tu peux aussi invalider les caches des autres lignes,
    % mais pas obligatoire; on le fait léger:
    for r = 1:n
        if r == keepRow, continue; end
        if numel(app.DSCache) >= r
            app.DSCache{r} = [];
        end
    end

    app.IsUpdating = false;
end

        
        function data = sanitizeEventTableData_(app, data, itemsCls)
    if isempty(data)
        data = cell(0,4);
        return;
    end

    if nargin < 3 || isempty(itemsCls)
        itemsCls = {'<none>'};
    end
    defaultCls = itemsCls{1};

    n = size(data,1);
    out = cell(n,4);

    for i=1:n
        % col1 Name (char)
        out{i,1} = app.safeCharScalar_(data{i,1}, '');

        % col2 Type (must be Start/End)
        t = app.safeCharScalar_(data{i,2}, 'Start');
        if ~ismember(t, {'Start','End'})
            t = 'Start';
        end
        out{i,2} = t;

        % col3/4 popup values must be char AND in itemsCls
        v3 = app.safeEventClassValue_(data{i,3}, defaultCls);
        if isempty(strtrim(v3)) || ~ismember(v3, itemsCls)
            v3 = defaultCls;
        end
        out{i,3} = v3;

        v4 = app.safeEventClassValue_(data{i,4}, defaultCls);
        if isempty(strtrim(v4)) || ~ismember(v4, itemsCls)
            v4 = defaultCls;
        end
        out{i,4} = v4;
    end

    data = out;
end

function out = safeCharScalar_(~, v, fallback)
    if nargin < 3, fallback = ''; end
    out = char(string(fallback));
    try
        s = string(v);
        if isempty(s)
            return;
        end
        s = s(1);
        if ismissing(s) || strlength(s) == 0
            return;
        end
        out = char(s);
    catch
    end
end

function tf = isPlaceholderClass_(~, v)
    tf = true;
    try
        s = string(v);
        if isempty(s)
            return;
        end
        s = strip(s(1));
        if ismissing(s) || strlength(s) == 0
            return;
        end
        if any(strcmpi(s, ["missing","undefined","<missing>","<undefined>"]))
            return;
        end
        tf = false;
    catch
        tf = true;
    end
end

function out = safeEventClassValue_(app, v, fallback)
    if nargin < 3 || isempty(fallback), fallback = '<none>'; end
    if app.isPlaceholderClass_(v)
        out = char(string(fallback));
    else
        out = app.safeCharScalar_(v, fallback);
    end
end

function cls = sanitizeLabelClasses_(app, cls)
    if isempty(cls)
        cls = {};
        return;
    end

    if isstring(cls)
        cls = cellstr(cls);
    elseif ~iscell(cls)
        cls = cellstr(string(cls));
    end

    out = {};
    for i = 1:numel(cls)
        v = cls{i};
        if app.isPlaceholderClass_(v)
            continue;
        end
        vv = app.safeCharScalar_(v, '');
        if isempty(strtrim(vv))
            continue;
        end
        out{end+1} = vv; %#ok<AGROW>
    end

    cls = unique(out, 'stable');
end


        function row = getCurrentROIRow(app)
            sel = app.UIROITable.Selection;
            if isempty(sel)
                row = NaN;
            else
                row = sel(1);
            end
        end

        function mp = getOrCreateSettingsMap(app, roiRow)
            if numel(app.DSSettings) < roiRow || isempty(app.DSSettings{roiRow})
                mp = containers.Map('KeyType','char','ValueType','any');
                app.DSSettings{roiRow} = mp;
            else
                mp = app.DSSettings{roiRow};
                if ~isa(mp,'containers.Map')
                    mp = containers.Map('KeyType','char','ValueType','any');
                    app.DSSettings{roiRow} = mp;
                end
            end
        end

        function roisIdx = parseROIString(~, str, nMax)
            if isnumeric(str)
                roisIdx = str;
                roisIdx = roisIdx(roisIdx>=1 & roisIdx<=nMax);
                return;
            end
            s = strtrim(char(str));
            if isempty(s)
                roisIdx = 1:nMax;
                return;
            end
            s = regexprep(s,'[,;]',' ');
            s = regexprep(s,'\s+',' ');
            try
                roisIdx = str2num(s); %#ok<ST2NM>
            catch
                roisIdx = [];
            end
            roisIdx = unique(roisIdx);
            roisIdx = roisIdx(roisIdx>=1 & roisIdx<=nMax);
        end

  function refreshDataseriesTable(app, roiRow)
    % Clear if invalid
    if roiRow < 1 || roiRow > numel(app.Data.target) || isempty(app.Data.target{roiRow})
        app.UIDataseriesTable.Data = {};
        app.CurrentDSKeys = string.empty;
        return;
    end

    tgt  = app.Data.target{roiRow};
    rois = tgt.roi(:);
    if isempty(rois)
        app.UIDataseriesTable.Data = {};
        app.CurrentDSKeys = string.empty;
        return;
    end

    % Parse ROI selection string
    raw = app.UIROITable.Data{roiRow,4};
    idx = parseROIString(app, raw, numel(rois));
    if isempty(idx), idx = 1:numel(rois); end

    % -------------------------
    % Cache lookup
    % -------------------------
    useCache = (numel(app.DSCache) >= roiRow) && ~isempty(app.DSCache{roiRow}) ...
        && isstruct(app.DSCache{roiRow}) && isfield(app.DSCache{roiRow},'rows') ...
        && isfield(app.DSCache{roiRow},'dsKeys') && isfield(app.DSCache{roiRow},'labelMap');

    if useCache
        rows     = app.DSCache{roiRow}.rows;
        dsKeys   = app.DSCache{roiRow}.dsKeys;
        labelMap = app.DSCache{roiRow}.labelMap;
    else
    targetN = 10;

    raw = app.UIROITable.Data{roiRow,4};
    idx = parseROIString(app, raw, numel(rois));
    if isempty(idx), idx = 1:numel(rois); end

    % Random order without replacement (equivalent to "pick random until 10")
    order = idx(randperm(numel(idx)));

    rpValid = [];
    labelMap = [];

    % Scan all ROIs until we have 10 "valid" ones (i.e. collectDataseriesRows returns rows)
    for k = 1:numel(order)
        rpi = order(k);

        [r1, k1, lm1] = app.collectDataseriesRows(rois, rpi); %#ok<ASGLU>

        if ~isempty(r1) && size(r1,1) > 0
            rpValid(end+1) = rpi; %#ok<AGROW>
            if ~isempty(lm1), labelMap = lm1; end
            if numel(rpValid) >= targetN
                break;
            end
        end
    end

    if isempty(rpValid)
        fprintf('[refreshDataseriesTable] No dataseries rows found in any ROI for roiRow=%d.\n', roiRow);
        app.UIDataseriesTable.Data = {};
        app.CurrentDSKeys = string.empty;

        app.DSCache{roiRow} = struct('rows', {{}}, 'dsKeys', string.empty, 'labelMap', labelMap);
        return;
    end

    % Build final rows/dsKeys/labelMap from the valid ROIs (up to 10)
    rpValid = rpValid(1:min(targetN, numel(rpValid)));
    [rows, dsKeys, labelMap] = app.collectDataseriesRows(rois, rpValid);

    app.DSCache{roiRow} = struct('rows',{rows}, 'dsKeys', dsKeys, 'labelMap', labelMap);
end


    % Restore settings for this ROI row
    mp = app.getOrCreateSettingsMap(roiRow);

    % Apply stored Display/Compare if present, else default false/false
    n = size(rows,1);
    data = cell(n,5);
    for i = 1:n
        key = char(dsKeys(i));
        dispVal = false;
        compVal = false;

        if isKey(mp, key)
            st = mp(key);
            if isstruct(st)
                if isfield(st,'display'), dispVal = logical(st.display); end
                if isfield(st,'compare'), compVal = logical(st.compare); end
            end
        else
            % init default in map (so it persists)
            mp(key) = struct('display', dispVal, 'compare', compVal);
        end

        data{i,1} = dispVal;
        data{i,2} = compVal;
        data{i,3} = rows{i,1}; % dataseries name
        data{i,4} = rows{i,2}; % variable name
        data{i,5} = rows{i,3}; % type
    end
    app.DSSettings{roiRow} = mp;

    % Push table
    app.IsUpdating = true;
    app.UIDataseriesTable.Data = data;
    app.UIDataseriesTable.ColumnEditable = [true true false false false];
    app.IsUpdating = false;

    app.CurrentDSKeys = dsKeys;

    app.CurrentEventKey = '';
    app.IsUpdating = true;

    % reset event rules table
    app.UIEventTable.Data = cell(0,4);

    app.UIEventTable.Enable     = 'off';
    app.AddRuleButton.Enable    = 'off';
    app.RemoveRuleButton.Enable = 'off';

    app.IsUpdating = false;

    % (Optionnel pour plus tard) populate Start/Stop based on label/event-type vars
    % For now, keep dropdowns untouched.

    % -------- local helper --------
    function tf = localROIHasData(r)
        tf = false;

        % Try common containers in a robust way
        try
            if isstruct(r) && isfield(r,'data') && ~isempty(r.data), tf = true; return; end
        catch, end
        try
            if isobject(r) && isprop(r,'data') && ~isempty(r.data), tf = true; return; end
        catch, end

        try
            if isstruct(r) && isfield(r,'dataseries') && ~isempty(r.dataseries), tf = true; return; end
        catch, end
        try
            if isobject(r) && isprop(r,'dataseries') && ~isempty(r.dataseries), tf = true; return; end
        catch, end

        % Fallback: if ROI has any fields that look like stored channels/tables
        % (kept conservative to avoid false positives)
        try
            if isstruct(r)
                fn = fieldnames(r);
                tf = any(strcmpi(fn,'ts')) || any(strcmpi(fn,'timeseries'));
            end
        catch, end
    end
end


        function [rows, dsKeys, labelMap] = collectDataseriesRows(app, rois, roiIdxSample)
% labelMap: containers.Map('key' -> cellstr categories)
labelMap = containers.Map('KeyType','char','ValueType','any');

% Return:
% rows: {dsName, varName, typeStr} one row per variable
% dsKeys: unique key per row (dsName|varName)

mpType = containers.Map('KeyType','char','ValueType','char'); % key -> type

for k = 1:numel(roiIdxSample)
    rr = rois(roiIdxSample(k));

    % Load only data (but don't fail silently without at least a warning)
    try
        rr.load('data');
    catch ME
        % keep going; maybe rr.data is already in memory
        warning('ROIclassiMismatchGUI:loadDataFailed', ...
            'roi.load(''data'') failed for ROI %s: %s', rr.id, ME.message);
    end

    % Access dataseries array
    try
        dss = rr.data;
    catch
        dss = [];
    end
    if isempty(dss), continue; end
    dss = dss(:);

    for j = 1:numel(dss)
        ds = dss(j);

        % ---- dataseries name ----
        dsName = '';
        if isprop(ds,'groupid') && ~isempty(ds.groupid)
            dsName = char(ds.groupid);
        elseif isprop(ds,'name') && ~isempty(ds.name)
            dsName = char(ds.name);
        else
            dsName = sprintf('dataseries_%d', j);
        end

        % ---- container in ds.data ----
        if ~isprop(ds,'data'), continue; end
        dt = ds.data;
        if isempty(dt), continue; end

        % Normalize into a list of (varName, varValue)
        [vnames, vvals] = app.enumerateVariables(dt);

for iv = 1:numel(vnames)

    varName = vnames{iv};
    key = sprintf('%s|%s', dsName, varName);

    val = vvals{iv};

    t = app.inferVarType(val);
    app.mergeType(mpType, key, t);

    % collect categories for label vars
    if strcmp(t,'label')
        cls = app.getLabelClasses(val);   % cellstr
        if ~isempty(cls)
            if ~isKey(labelMap, key)
                labelMap(key) = cls;
            else
                old = labelMap(key);  % cellstr
                labelMap(key) = unique([old(:); cls(:)], 'stable');
            end
        end
    end
end

    end
end

% Build sorted outputs
mapKeys = mpType.keys;               % cell array char
allKeys = string(mapKeys);

allTypes = strings(size(allKeys));
dsNames  = strings(size(allKeys));
varNames = strings(size(allKeys));

for i = 1:numel(allKeys)
    kk = char(allKeys(i));
    allTypes(i) = string(mpType(kk));
    sp = strsplit(kk,'|');
if numel(sp) < 2
    sp = {sp{1}, ''};
end

    dsNames(i) = string(sp{1});
    varNames(i) = string(sp{2});
end

% --- robust sorting ---
n = numel(allKeys);
if n == 0
    rows = cell(0,3);
    dsKeys = string.empty;
    return;
end

if isempty(varNames) || numel(varNames) ~= n
    % fallback: sort by dataseries only
    [~, ord] = sort(dsNames);
else
    M = [dsNames(:) varNames(:)];
    % if for any reason M has only 1 column, sort on 1; else sort on 2
    if size(M,2) == 1
        [~, ord] = sort(M(:,1));
    else
        [~, ord] = sortrows(M,[1 2]);
    end
end

allKeys  = allKeys(ord);
allTypes = allTypes(ord);
dsNames  = dsNames(ord);
varNames = varNames(ord);


rows = cell(numel(allKeys),3);
for i = 1:numel(allKeys)
    rows{i,1} = char(dsNames(i));
    rows{i,2} = char(varNames(i));
    rows{i,3} = char(allTypes(i));
end

dsKeys = allKeys;
end

function cls = getLabelClasses(app, x)
cls = {};
try
    if iscategorical(x)
        cls = cellstr(categories(x));
        cls = app.sanitizeLabelClasses_(cls);
        return;
    end
    if isstring(x)
        u = unique(x(:),'stable');
        u = u(~ismissing(u));
        u = u(strlength(u)>0);
        cls = cellstr(u);
        cls = app.sanitizeLabelClasses_(cls);
        return;
    end
    if ischar(x)
        s = string(x);
        if ~ismissing(s) && strlength(s)>0, cls = {char(s)}; end
        cls = app.sanitizeLabelClasses_(cls);
        return;
    end
    if iscell(x)
        s = strings(numel(x),1);
        for i=1:numel(x)
            if isstring(x{i}) && ~isempty(x{i}) && ~any(ismissing(x{i})), s(i)=x{i}(1);
            elseif ischar(x{i}), s(i)=string(x{i});
            else, s(i)=missing;
            end
        end
        u = unique(s,'stable');
        u = u(~ismissing(u));
        u = u(strlength(u)>0);
        cls = cellstr(u);
        cls = app.sanitizeLabelClasses_(cls);
        return;
    end
catch
end
cls = {};
end


function [vnames, vvals] = enumerateVariables(app, dt)
% Returns cell arrays vnames{n}, vvals{n} for dt that can be:
% - table / timetable
% - struct
% - cell array (possibly containing tables/structs)

vnames = {};
vvals  = {};

if istable(dt) || istimetable(dt)
    vn = dt.Properties.VariableNames;
    vnames = vn(:)';
    vvals  = cell(1,numel(vnames));
    for i = 1:numel(vnames)
        vvals{i} = dt.(vnames{i});
    end
    return;
end

if isstruct(dt)
    fn = fieldnames(dt);
    vnames = fn(:)';
    vvals  = cell(1,numel(vnames));
    for i = 1:numel(vnames)
        vvals{i} = dt.(vnames{i});
    end
    return;
end

if iscell(dt)
    % merge variables across elements
    for c = 1:numel(dt)
        if isempty(dt{c}), continue; end
        [vn2, vv2] = app.enumerateVariables(dt{c});
        for i = 1:numel(vn2)
            vnames{end+1} = vn2{i}; %#ok<AGROW>
            vvals{end+1}  = vv2{i}; %#ok<AGROW>
        end
    end
    % make unique by keeping first occurrence
    [vnames, ia] = unique(vnames,'stable');
    vvals = vvals(ia);
    return;
end

% fallback: one blob
vnames = {'data'};
vvals  = {dt};
end


        function mergeType(~, mpType, key, newType)
            if ~isKey(mpType, key)
                mpType(key) = newType;
            else
                oldType = mpType(key);
                if ~strcmp(oldType, newType)
                    mpType(key) = 'mixed';
                end
            end
        end

        function t = inferVarType(~, x)
            % Map to your "double, label" style, with fallback
            if isnumeric(x) || islogical(x)
                t = 'double';
                return;
            end
            if iscategorical(x) || isstring(x) || ischar(x)
                t = 'label';
                return;
            end
            % some labels might be cellstr
            if iscell(x)
                if all(cellfun(@(c) ischar(c) || isstring(c), x(:)))
                    t = 'label';
                    return;
                end
            end
            t = class(x);
        end

        % ---- displayROIs: same logic as ROIAdjustGUI (short version) ----
        function tf = hasValidROIs(~, obj)
            tf = false;
            if ~isprop(obj,'roi') || isempty(obj.roi), return; end
            r = obj.roi(1);
            if ~isa(r,'roi') || isempty(r.id), return; end
            tf = true;
        end

        function displayROIs(app, option)
            s = app.Data.st;
            cc = 1;

            displaystr2 = {};
            varstr      = {};
            target      = {};

            % Projects
            for i = 1:numel(s.Project)
                proj = s.Project{i};

                if ~isempty(s.Projectpos)
                    for k = 1:numel(s.Projectpos{i})
                        tmp = evalin('base', sprintf('%s.fov(%d)', proj, k));
                        if hasValidROIs(app,tmp)
                            varstr{cc} = sprintf('%s.fov(%d)', proj, k);
                            displaystr2{cc,1} = proj;
                            displaystr2{cc,2} = s.Projectpos{i}{k};
                            displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                            target{cc} = tmp;
                            cc = cc + 1;
                        end
                    end
                end

                if ~isempty(s.Projectclassi)
                    for k = 1:numel(s.Projectclassi{i})
                        val = k;
                        if nargin == 3 && ~isempty(option)
                            mp = evalin('base', proj);
                            for l = 1:numel(mp.processing.classification)
                                if strcmp(mp.processing.classification(l).strid, option)
                                    val = l; break
                                end
                            end
                        end
                        tmp = evalin('base', sprintf('%s.processing.classification(%d)', proj, val));
                        if hasValidROIs(app,tmp)
                            varstr{cc} = sprintf('%s.processing.classification(%d)', proj, val);
                            displaystr2{cc,1} = proj;
                            displaystr2{cc,2} = s.Projectclassi{i}{k};
                            displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                            target{cc} = tmp;
                            cc = cc + 1;
                        end
                    end
                end
            end

            % Standalone classifiers
            for i = 1:numel(s.Classifier)
                clas = s.Classifier{i};
                if isempty(clas), continue; end
                if isstring(clas), clas = char(clas(1)); end
                if ~ischar(clas), continue; end
                try
                    tmp = evalin('base', clas);
                catch
                    continue;
                end
                if hasValidROIs(app,tmp)
                    varstr{cc} = clas;
                    displaystr2{cc,1} = '';
                    displaystr2{cc,2} = clas;
                    displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                    target{cc} = tmp;
                    cc = cc + 1;
                end
            end

            app.Data.target      = target;
            app.Data.varstr      = varstr;
            app.Data.displaystr2 = displaystr2;

            nRows = size(displaystr2,1);
            d = cell(nRows,4);
            for i = 1:nRows
                d{i,1} = false;
                d{i,2} = displaystr2{i,1};
                d{i,3} = displaystr2{i,2};
                d{i,4} = displaystr2{i,3};
            end

            app.UIROITable.Data = d;
            app.UIROITable.ColumnEditable = [true false false true];
            app.UIROITable.ColumnWidth = {50, 200, 220, 'auto'};
        end

        function deferUI_(app, fcn)
    % Execute fcn after current callback returns (avoids WebUITable race)
    try
        t = timer('ExecutionMode','singleShot', ...
                  'StartDelay', 0, ...
                  'TimerFcn', @(~,~)app.safeDeferCall_(fcn));
        start(t);
    catch
        % fallback (shouldn't happen often)
        try, fcn(); end
    end
end

function refreshEventUIFromCurrentKey_(app)
    if app.IsUpdating, return; end
    roiRow = app.getCurrentROIRow();
    if isnan(roiRow) || isempty(app.CurrentEventKey)
        return;
    end

    key = app.CurrentEventKey;

    % Need compare mode ON to enable UI
    nRows = min(size(app.UIDataseriesTable.Data,1), numel(app.CurrentDSKeys));
    comp = false(nRows,1);
    for i = 1:nRows
        try, comp(i) = logical(app.UIDataseriesTable.Data{i,2}); end
    end
    isCompareMode = (nnz(comp) == 2);

    app.IsUpdating = true;
    if ~isCompareMode
        app.UIEventTable.Enable      = 'off';
        app.AddRuleButton.Enable     = 'off';
        app.RemoveRuleButton.Enable  = 'off';
        app.UIEventTable.Data        = cell(0,4);
        app.IsUpdating = false;
        return;
    else
        app.UIEventTable.Enable      = 'on';
        app.AddRuleButton.Enable     = 'on';
        app.RemoveRuleButton.Enable  = 'on';
    end
    app.IsUpdating = false;

    % Determine type of selected DS row (needs selection)
    sel = app.UIDataseriesTable.Selection;
    if isempty(sel), return; end
    r = sel(1);
    if r > numel(app.CurrentDSKeys), return; end

    tbl = app.UIDataseriesTable.Data;
    typ = "";
    try, typ = string(tbl{r,5}); end

    cls = app.getClassesForKey(roiRow, key);

    % Build popup items (never empty)
    if isstring(cls), cls = cellstr(cls); end
    if ~iscell(cls), cls = cellstr(string(cls)); end

    out = {};
    for ii=1:numel(cls)
        v = cls{ii};
        if isstring(v), v = char(v); end
        if ischar(v) && ~isempty(strtrim(v))
            out{end+1} = v; %#ok<AGROW>
        end
    end
    out = unique(out,'stable');
    if isempty(out), out = {'<none>'}; end
    itemsCls = out(:)';

    app.IsUpdating = true;

    if typ ~= "label" || isempty(cls)
        app.UIEventTable.ColumnFormat = {'char',{'Start','End'},'char','char'};
        app.UIEventTable.Data = cell(0,4);
        app.IsUpdating = false;
        return;
    end

    app.UIEventTable.ColumnFormat = {'char',{'Start','End'},itemsCls,itemsCls};

    % Load rules + sanitize against popup items
    mp = app.getOrCreateSettingsMap(roiRow);
    rules = struct('name',{},'type',{},'from',{},'to',{});
    if isKey(mp,key)
        st = mp(key);
        if isstruct(st) && isfield(st,'eventRules')
            rules = st.eventRules;
        end
    end

    valid = string(itemsCls(:));
    defaultCls = string(itemsCls{1});
    for i=1:numel(rules)
        if strlength(string(rules(i).from))==0 || ~any(valid == string(rules(i).from))
            rules(i).from = defaultCls;
        end
        if strlength(string(rules(i).to))==0 || ~any(valid == string(rules(i).to))
            rules(i).to = defaultCls;
        end
    end

    % Clear selection before resetting Data (reduces glitches)
    app.UIEventTable.Selection = [];

    tmp = app.rulesToEventTable(rules);
tmp = app.sanitizeEventTableData_(tmp, itemsCls);
app.UIEventTable.Data = tmp;
    app.IsUpdating = false;
end

function safeDeferCall_(app, fcn)
    % called by timer
    try
        if isvalid(app) && isvalid(app.UIFigure)
            fcn();
        end
    catch
    end
    % timer auto-destroyed by MATLAB; if not, stop/delete safely
    try
        st = timerfindall;
        % no-op; leaving cleanup to MATLAB is fine
    catch
    end
end


function dsKeys = collectSelectedDSKeys_(app)

keys = strings(0,1);

for roiRow = 1:numel(app.DSSettings)
    mp = app.DSSettings{roiRow};
    if isempty(mp) || ~isa(mp,'containers.Map'), continue; end

    ks = mp.keys; % cellstr
    for i = 1:numel(ks)
        k = string(ks{i});
        st = mp(ks{i});
        if ~isstruct(st), continue; end

        dispVal = false; compVal = false;
        if isfield(st,'display'), dispVal = logical(st.display); end
        if isfield(st,'compare'), compVal = logical(st.compare); end

        if dispVal || compVal
            keys(end+1,1) = k; %#ok<AGROW>
        end
    end
end

dsKeys = unique(keys, 'stable');
dsKeys = dsKeys(strlength(dsKeys)>0);
end


        function roiList = collectSelectedROIs_(app)

roiList = struct('groupRow',{},'roiIdx',{},'roiObj',{},'label',{});

d = app.UIROITable.Data;
if isempty(d), return; end

for r = 1:size(d,1)

    % colonne 1 = Select
    try
        isOn = logical(d{r,1});
    catch
        isOn = false;
    end
    if ~isOn, continue; end

    if r<1 || r>numel(app.Data.target) || isempty(app.Data.target{r}), continue; end
    tgt = app.Data.target{r};
    if ~isprop(tgt,'roi') || isempty(tgt.roi), continue; end

    rois = tgt.roi(:);

    % colonne 4 = "Select ROIs" string
    raw = d{r,4};
    idx = app.parseROIString(raw, numel(rois));
    if isempty(idx), idx = 1:numel(rois); end

    for k = 1:numel(idx)
        rr = idx(k);
        if rr<1 || rr>numel(rois), continue; end

        roiObj = rois(rr);

        cc = numel(roiList)+1;
        roiList(cc).groupRow = r;
        roiList(cc).roiIdx   = rr;
        roiList(cc).roiObj   = roiObj;

        % label: Project/Classifier + roi index + roi id
        lab = "row" + r + " roi#" + rr;
        try
            if isa(roiObj,'roi') && ~isempty(roiObj.id)
                lab = lab + " (" + string(roiObj.id) + ")";
            end
        catch
        end
        roiList(cc).label = char(lab);
    end
end
        end


function cls = getClassesForKey(app, roiRow, key)
    cls = {};
    try
        if numel(app.DSCache) >= roiRow && ~isempty(app.DSCache{roiRow}) ...
                && isstruct(app.DSCache{roiRow}) && isfield(app.DSCache{roiRow},'labelMap')
            lm = app.DSCache{roiRow}.labelMap;
            if isa(lm,'containers.Map') && isKey(lm, key)
                cls = lm(key); % cellstr
                cls = app.sanitizeLabelClasses_(cls);
            end
        end
    catch
        cls = {};
    end
end

function rules = eventTableToRules(~, data)
    % data: cell Nx4 : {Name, Type, Class1, Class2}
    if isempty(data)
        rules = struct('name',{},'type',{},'from',{},'to',{});
        return;
    end
    n = size(data,1);
    rules = repmat(struct('name',"",'type',"",'from',"",'to',""), n, 1);
    for i=1:n
        rules(i).name = string(data{i,1});
        rules(i).type = string(data{i,2});
        rules(i).from = string(data{i,3});
        rules(i).to   = string(data{i,4});
    end
end

function data = rulesToEventTable(~, rules)
    if isempty(rules)
        data = cell(0,4);
        return;
    end
    n = numel(rules);
    data = cell(n,4);
    for i=1:n
        data{i,1} = char(string(rules(i).name));
        data{i,2} = char(string(rules(i).type));
        data{i,3} = char(string(rules(i).from));
        data{i,4} = char(string(rules(i).to));
    end
end

function setEventTable_(app, itemsCls, rules)
    if isempty(itemsCls)
        itemsCls = {'<none>'};
    end
    itemsCls = itemsCls(:)';  % row cellstr

    app.IsUpdating = true;

    % freeze UI
    app.UIEventTable.Enable = 'off';

    % clear first (important)
    app.UIEventTable.Data = cell(0,4);

    % set format
    app.UIEventTable.ColumnFormat = {'char', {'Start','End'}, itemsCls, itemsCls};

    drawnow limitrate;  % let WebUITable digest the format

    % set data (sanitized)
    data = app.rulesToEventTable(rules);
    data = app.sanitizeEventTableData_(data, itemsCls);
    app.UIEventTable.Data = data;

    % unfreeze
    app.UIEventTable.Enable = 'on';
    app.IsUpdating = false;
end


function saveEventRulesForCurrentKey_(app)
    if app.IsUpdating, return; end
    roiRow = app.getCurrentROIRow();
    if isnan(roiRow) || isempty(app.CurrentEventKey), return; end

    key = app.CurrentEventKey;
    mp  = app.getOrCreateSettingsMap(roiRow);

    % keep existing display/compare flags
    dispVal=false; compVal=false;
    if isKey(mp,key)
        st = mp(key);
        if isstruct(st)
            if isfield(st,'display'), dispVal = logical(st.display); end
            if isfield(st,'compare'), compVal = logical(st.compare); end
        end
    end

    rules = app.eventTableToRules(app.UIEventTable.Data);
    mp(key) = struct('display',dispVal,'compare',compVal,'eventRules',rules);
    app.DSSettings{roiRow} = mp;
end


function propagateRulesIfCompare_(app)
    roiRow = app.getCurrentROIRow();
    if isnan(roiRow), return; end

    mp = app.getOrCreateSettingsMap(roiRow);
    if isempty(mp) || ~isa(mp,'containers.Map'), return; end

    % find compare keys in current table
    tbl = app.UIDataseriesTable.Data;
    if isempty(tbl) || isempty(app.CurrentDSKeys), return; end

    nRows = min(size(tbl,1), numel(app.CurrentDSKeys));
    compIdx = false(nRows,1);
    for i=1:nRows
        try, compIdx(i) = logical(tbl{i,2}); catch, compIdx(i)=false; end
    end
    keys = app.CurrentDSKeys(1:nRows);
    compareKeys = keys(compIdx);
    compareKeys = compareKeys(strlength(compareKeys)>0);

    if numel(compareKeys) ~= 2, return; end

    k1 = char(compareKeys(1));
    k2 = char(compareKeys(2));

    % choose reference = first compare key
    if ~isKey(mp,k1), return; end
    st1 = mp(k1);
    if ~isstruct(st1) || ~isfield(st1,'eventRules'), return; end

    rulesRef = st1.eventRules;

    % copy to k2 (preserve display/compare)
    disp2=false; comp2=true;
    if isKey(mp,k2)
        st2 = mp(k2);
        if isstruct(st2)
            if isfield(st2,'display'), disp2 = logical(st2.display); end
            if isfield(st2,'compare'), comp2 = logical(st2.compare); end
        end
    end
    mp(k2) = struct('display',disp2,'compare',comp2,'eventRules',rulesRef);
    app.DSSettings{roiRow} = mp;

    % if we are currently viewing k2 in the UI, refresh table
    % if ~isempty(app.CurrentEventKey) && strcmp(app.CurrentEventKey, k2)
    %     app.IsUpdating = true;
    %     app.UIEventTable.Data = app.rulesToEventTable(rulesRef);
    %     app.IsUpdating = false;
    % end
end

function classif = getClassifierFromTarget_(app, tgt)
%GETCLASSIFIERFROMTARGET_  Return a classi classifier object from a target.
%
% tgt can be:
%   - a classi object                       -> returned as-is
%   - a struct/object that CONTAINS a classi -> best-effort extraction
%   - a fov/position-like object            -> returns []
%
% This function does NOT build "info" structs and does NOT rely on ownerVarStr.

classif = [];

try
    if isempty(tgt)
        return;
    end

    % ------------------------------------------------------------
    % 1) Direct case: already a classifier
    % ------------------------------------------------------------
    if isa(tgt,'classi')
        classif = tgt;
        return;
    end

    % ------------------------------------------------------------
    % 2) Common indirect cases: tgt holds the classifier somewhere
    % ------------------------------------------------------------

    % --- case: struct with fields that look like a classifier ---
    if isstruct(tgt)
        % common fields: .classif / .classifier / .classification / .obj / .handle
        candFields = {'classif','classifier','classification','classiObj','obj','handle'};
        for k = 1:numel(candFields)
            f = candFields{k};
            if isfield(tgt,f) && ~isempty(tgt.(f)) && isa(tgt.(f),'classi')
                classif = tgt.(f);
                return;
            end
        end

        % sometimes the struct itself mimics the classifier interface,
        % but since you want the object (not info), we do not fabricate one.
        return;
    end

    % --- case: object that exposes a classifier property ---
    if isobject(tgt)
        % common property names
        candProps = {'classif','classifier','classification','classiObj','obj','handle'};
        for k = 1:numel(candProps)
            p = candProps{k};
            try
                if isprop(tgt,p)
                    v = tgt.(p);
                    if ~isempty(v) && isa(v,'classi')
                        classif = v;
                        return;
                    end
                end
            catch
            end
        end

        % sometimes tgt itself has classifier signature but is NOT a classi:
        % (has run/path/strid). You explicitly do NOT want a struct proxy here.
        return;
    end

catch
    classif = [];
end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.UIFigure.Name = 'ROI classi mismatch';

            s = gatherVariablesFromWorkspace();
            app.Data.st = s;

            app.Data.target      = {};
            app.Data.varstr      = {};
            app.Data.displaystr2 = {};

            displayROIs(app, []);

            if ~isempty(app.UIROITable.Data)
                app.UIROITable.Selection = [1 1];
                UIROITableSelectionChanged(app, struct());
            end
        end

        % Cell edit callback: UIROITable
        function UIROITableCellEdit(app, event)
              if app.IsUpdating, return; end

            ind = event.Indices;
            if isempty(ind), return; end

            r = ind(1); c = ind(2);

            % If user edits "Select ROIs" (col 4), auto-select the group (col 1)
          if c == 4
    % Editing ROI list implies selecting this group, and ONLY this group
    app.enforceSingleSelectedRow_(r);

    if numel(app.DSCache) >= r
        app.DSCache{r} = [];
    end

    % force selection on that row and refresh dataseries table
    app.UIROITable.Selection = [r 1];
    UIROITableSelectionChanged(app, struct());
    return;
end

         if c == 1
    % If user turns ON this row => turn OFF all others
    isOn = false;
    try
        isOn = logical(app.UIROITable.Data{r,1});
    catch
    end

    if isOn
        app.enforceSingleSelectedRow_(r);
        app.UIROITable.Selection = [r 1];
        UIROITableSelectionChanged(app, struct());
    else
        % If user unchecks the only selected row, you can either allow "none"
        % or re-check it. I suggest allow none (safer UX):
        % (nothing to do)
    end
end

            
        end

        % Selection changed function: UIROITable
        function UIROITableSelectionChanged(app, event)
           if app.IsUpdating, return; end

    sel = app.UIROITable.Selection;
    if isempty(sel), return; end
    row = sel(1);

    % <-- add this
    app.enforceSingleSelectedRow_(row);

    app.LastROIRow = row;
    refreshDataseriesTable(app, row);
            
        end

        % Cell edit callback: UIDataseriesTable
        function UIDataseriesTableCellEdit(app, event)


    if app.IsUpdating, return; end

    ind = event.Indices;
    if isempty(ind), return; end
    r = ind(1);
    c = ind(2);

    % Only Display / Compare editable
    if c ~= 1 && c ~= 2, return; end

    roiRow = app.getCurrentROIRow();
    if isnan(roiRow), return; end

    if r > numel(app.CurrentDSKeys) || strlength(app.CurrentDSKeys(r)) == 0
        return;
    end

    key = char(app.CurrentDSKeys(r));
    mp  = app.getOrCreateSettingsMap(roiRow);

    % ---- Read current values ----
    data = app.UIDataseriesTable.Data;
    dispVal = false; compVal = false;
    try, dispVal = logical(data{r,1}); end
    try, compVal = logical(data{r,2}); end

    % ---- Find reference compare key (already checked, excluding self) ----
    refKey = '';
    if c == 2 && compVal
        for ii = 1:numel(app.CurrentDSKeys)
            k2 = char(app.CurrentDSKeys(ii));
            if isempty(k2) || strcmp(k2,key), continue; end
            if isKey(mp,k2)
                st2 = mp(k2);
                if isstruct(st2) && isfield(st2,'compare') && logical(st2.compare)
                    refKey = k2;
                    break;
                end
            end
        end
    end

    % ---- Compare implies Display ----
    if c == 2 && compVal && ~dispVal
        app.IsUpdating = true;
        app.UIDataseriesTable.Data{r,1} = true;
        app.IsUpdating = false;
        dispVal = true;
        data = app.UIDataseriesTable.Data;
    end

    % ---- Enforce max 2 compares ----
    if c == 2 && compVal
        compFlags = false(size(data,1),1);
        for ii = 1:size(data,1)
            try, compFlags(ii) = logical(data{ii,2}); end
        end

        idxOn = find(compFlags);
        if numel(idxOn) > 2
            idxToUncheck = max(idxOn);

            app.IsUpdating = true;
            app.UIDataseriesTable.Data{idxToUncheck,2} = false;
            app.IsUpdating = false;

            key2 = char(app.CurrentDSKeys(idxToUncheck));
            rules2 = struct('name',{},'type',{},'from',{},'to',{});
            if isKey(mp,key2)
                st2 = mp(key2);
                if isstruct(st2) && isfield(st2,'eventRules')
                    rules2 = st2.eventRules;
                end
            end

            disp2 = false;
            try, disp2 = logical(app.UIDataseriesTable.Data{idxToUncheck,1}); end
            mp(key2) = struct('display',disp2,'compare',false,'eventRules',rules2);

            if idxToUncheck == r
                compVal = false;
            end
        end
    end

    % ---- Harmonize event rules when 2nd compare is checked ----
    if c == 2 && compVal && ~isempty(refKey) && isKey(mp,refKey)

        stRef = mp(refKey);
        if isstruct(stRef) && isfield(stRef,'eventRules')
            rulesRef = stRef.eventRules;
        else
            rulesRef = struct('name',{},'type',{},'from',{},'to',{});
        end

        mp(key) = struct('display',dispVal,'compare',true,'eventRules',rulesRef);
        app.DSSettings{roiRow} = mp;

        % force selection on edited row
        app.IsUpdating = true;
        app.UIDataseriesTable.Selection = [r 1];
        app.IsUpdating = false;

        UIDataseriesTableSelectionChanged(app, struct());
      
        return;   % IMPORTANT: do not overwrite below
    end

    % ---- Normal save (preserve rules) ----
    rules = struct('name',{},'type',{},'from',{},'to',{});
    if isKey(mp,key)
        st = mp(key);
        if isstruct(st) && isfield(st,'eventRules')
            rules = st.eventRules;
        end
    end

    mp(key) = struct('display',dispVal,'compare',compVal,'eventRules',rules);
    app.DSSettings{roiRow} = mp;

    % ---- Refresh Event UI if Compare changed ----
    if c == 2
        app.IsUpdating = true;
        app.UIDataseriesTable.Selection = [r 1];
        app.IsUpdating = false;
        UIDataseriesTableSelectionChanged(app, struct());
    end


        end

        % Selection changed function: UIDataseriesTable
        function UIDataseriesTableSelectionChanged(app, event)
% Selection changed function: UIDataseriesTable


    if app.IsUpdating, return; end

    % -------------------------------
    % 1) Detect compare mode (needs exactly 2 compares)
    % -------------------------------
    nRows = min(size(app.UIDataseriesTable.Data,1), numel(app.CurrentDSKeys));
    comp = false(nRows,1);
    for i = 1:nRows
        try
            comp(i) = logical(app.UIDataseriesTable.Data{i,2});
        catch
            comp(i) = false;
        end
    end
    isCompareMode = (nnz(comp) == 2);

    % -------------------------------
    % 2) Enable / disable Event UI safely
    % -------------------------------
    if ~isCompareMode
        app.IsUpdating = true;

        app.CurrentEventKey = '';

        app.UIEventTable.Enable      = 'off';
        app.AddRuleButton.Enable     = 'off';
        app.RemoveRuleButton.Enable  = 'off';

        % reset to non-popup formats to avoid WebUITable formatting issues
        app.UIEventTable.Data        = cell(0,4);
        app.UIEventTable.ColumnFormat = {'char', {'Start','End'}, 'char', 'char'};
        drawnow limitrate;

        app.IsUpdating = false;
      
        return;
    else
        app.IsUpdating = true;

        app.UIEventTable.Enable      = 'on';
        app.AddRuleButton.Enable     = 'on';
        app.RemoveRuleButton.Enable  = 'on';

        app.IsUpdating = false;
    end

    % -------------------------------
    % 3) Get selected row + current key
    % -------------------------------
    sel = app.UIDataseriesTable.Selection;
    roiRow = app.getCurrentROIRow();

    if isempty(sel) || isnan(roiRow)
        app.CurrentEventKey = '';
       
        return;
    end

    r = sel(1);
    if r < 1 || r > numel(app.CurrentDSKeys)
        app.CurrentEventKey = '';
 
        return;
    end

    key = char(app.CurrentDSKeys(r));
    app.CurrentEventKey = key;

    % -------------------------------
    % 4) Check variable type and get label classes
    % -------------------------------
    tbl = app.UIDataseriesTable.Data;

    typ = "";
    try
        typ = string(tbl{r,5});
    catch
        typ = "";
    end

    cls = app.getClassesForKey(roiRow, key);

    % If not label or no classes -> blank event UI
    if typ ~= "label" || isempty(cls)
        app.IsUpdating = true;

        app.UIEventTable.Enable      = 'on'; % compare mode stays on
        app.UIEventTable.Data        = cell(0,4);
        app.UIEventTable.ColumnFormat = {'char', {'Start','End'}, 'char', 'char'};
        drawnow limitrate;

        app.IsUpdating = false;
      
        return;
    end

    % -------------------------------
    % 5) Build popup items (MUST NEVER be empty)
    % -------------------------------
    if isstring(cls)
        cls = cellstr(cls);
    end
    if ~iscell(cls)
        cls = cellstr(string(cls));
    end

    cls = cls(:);
    itemsCls = {};
    for ii = 1:numel(cls)
        v = cls{ii};
        if isempty(v), continue; end
        if app.isPlaceholderClass_(v), continue; end
        v = app.safeCharScalar_(v, '');
        if ischar(v) && ~isempty(strtrim(v))
            itemsCls{end+1} = v; %#ok<AGROW>
        end
    end

    itemsCls = unique(itemsCls, 'stable');
    if isempty(itemsCls)
        itemsCls = {'<none>'};  % critical placeholder
    end
    itemsCls = itemsCls(:)'; % row vector cellstr

    % -------------------------------
    % 6) Load rules and sanitize against popup list
    % -------------------------------
    mp = app.getOrCreateSettingsMap(roiRow);

    rules = struct('name',{},'type',{},'from',{},'to',{});
    if isKey(mp, key)
        st = mp(key);
        if isstruct(st) && isfield(st,'eventRules')
            rules = st.eventRules;
        end
    end

    valid = string(itemsCls(:));
    defaultCls = string(itemsCls{1});

    for i = 1:numel(rules)
        if ~isfield(rules(i),'from') || strlength(string(rules(i).from))==0 || ~any(valid == string(rules(i).from))
            rules(i).from = defaultCls;
        end
        if ~isfield(rules(i),'to')   || strlength(string(rules(i).to))==0   || ~any(valid == string(rules(i).to))
            rules(i).to   = defaultCls;
        end
    end

    % -------------------------------
    % 7) ATOMIC update of UIEventTable (avoid WebUITable race)
    % -------------------------------
    app.IsUpdating = true;

    app.UIEventTable.Enable = 'off';

    % clear first
    app.UIEventTable.Data = cell(0,4);

    % set popup formats
    app.UIEventTable.ColumnFormat = {'char', {'Start','End'}, itemsCls, itemsCls};
    drawnow limitrate;

    % set data (strict char + in-list)
    tmp = app.rulesToEventTable(rules);
    tmp = app.sanitizeEventTableData_(tmp, itemsCls);
    app.UIEventTable.Data = tmp;

    app.UIEventTable.Enable = 'on';
    drawnow limitrate;

    app.IsUpdating = false;


        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

                d = uiprogressdlg(app.UIFigure, ...
        'Title', 'Processing data...', ...
        'Message', 'Please wait while the ROI are being processed...', ...
        'Indeterminate', 'on');

    if app.IsUpdating, return; end

    % ---- args depuis GUI ----
    args = struct();
    args.BarHeight           = max(1, round(app.TrajheightEditField.Value));
    args.FrameWidth          = max(1, round(app.TrajframewidthEditField.Value));
    args.ShowColorbar        = logical(app.ShowcolorbarCheckBox.Value);
    args.LabelColormapName   = string(app.LabelcolormapEditField.Value);
    args.NumbersColormapName = string(app.NumberscolormapEditField.Value);

    % ---- collect ROIs ----
    roiList = app.collectSelectedROIs_();

    if isempty(roiList)
        close(d);
        uialert(app.UIFigure, "Aucune ROI sélectionnée.", "Proceed");
        return;
    end

 % ---- classifier object (preferred) ----
classif = [];
try
    roiRow = app.getCurrentROIRow();  % row currently active (and only one selected)
    tgt = [];
    if roiRow>=1 && roiRow<=numel(app.Data.target)
        tgt = app.Data.target{roiRow};
    end

    classif = app.getClassifierFromTarget_(tgt); % returns classi object or []
    if ~isempty(classif)
        fprintf('[Proceed] classifier strid=%s\n', char(string(classif.strid)));
    else
        fprintf('[Proceed] no classifier found (likely a FOV)\n');
    end
catch ME
    fprintf('[Proceed] getClassifierFromTarget_ failed: %s\n', ME.message);
    classif = [];
end


    % ---- keys from current dataseries table (current ROI row) ----
    roiRow = app.getCurrentROIRow();
    tbl = app.UIDataseriesTable.Data;

    if isnan(roiRow) || isempty(tbl) || isempty(app.CurrentDSKeys)
        close(d);
        uialert(app.UIFigure, "Aucune dataserie disponible (sélectionne d'abord un groupe ROI).", "Proceed");
        return;
    end

    % flags display/compare depuis la table (alignés avec app.CurrentDSKeys)
    nRows = min(size(tbl,1), numel(app.CurrentDSKeys));

    dispFlags = false(nRows,1);
    compFlags = false(nRows,1);
    for i = 1:nRows
        try, dispFlags(i) = logical(tbl{i,1}); catch, dispFlags(i) = false; end
        try, compFlags(i) = logical(tbl{i,2}); catch, compFlags(i) = false; end
    end

    displayKeys = app.CurrentDSKeys(1:nRows);
    displayKeys = displayKeys(dispFlags | compFlags);   % compare implique display, mais on garde robuste
    displayKeys = displayKeys(strlength(displayKeys)>0);

    compareKeys = app.CurrentDSKeys(1:nRows);
    compareKeys = compareKeys(compFlags);
    compareKeys = compareKeys(strlength(compareKeys)>0);

    mp = app.getOrCreateSettingsMap(roiRow);

    fprintf('[Proceed] roiRow=%d\n', roiRow);
kk = mp.keys;
fprintf('[Proceed] mp.Count=%d keys=%s\n', mp.Count, strjoin(string(kk), ', '));

for i=1:numel(compareKeys)
    k = char(compareKeys(i));
    if ~isKey(mp,k)
        fprintf('[Proceed] mp missing key "%s"\n', k);
    else
        st = mp(k);
        if isstruct(st) && isfield(st,'eventRules')
            fprintf('[Proceed] key "%s": eventRules size=%dx%d isempty=%d\n', ...
                k, size(st.eventRules,1), size(st.eventRules,2), isempty(st.eventRules));
        else
            fprintf('[Proceed] key "%s": no field eventRules\n', k);
        end
    end
end

% collect rules for displayed keys
% collect rules for keys that matter (compareKeys if compare, else displayKeys)
keysWanted = displayKeys;
if numel(compareKeys) > 0
    keysWanted = compareKeys; % compare => only these 2 are needed
end

eventRulesByKey = containers.Map('KeyType','char','ValueType','any');
for i=1:numel(keysWanted)
    k = char(keysWanted(i));
    rules = struct('name',{},'type',{},'from',{},'to',{});
    if isKey(mp,k)
        st = mp(k);
        if isstruct(st)
            % accept several possible field names (robust)
            if isfield(st,'eventRules') && ~isempty(st.eventRules)
                rules = st.eventRules;
            elseif isfield(st,'EventRules') && ~isempty(st.EventRules)
                rules = st.EventRules;
            elseif isfield(st,'rules') && ~isempty(st.rules)
                rules = st.rules;
            end
        end
    end
    eventRulesByKey(k) = rules;
end


    % ---- decide mode ----
    if numel(compareKeys) > 0
        % si l'utilisateur utilise Compare, on exige 2 clés
        if numel(compareKeys) ~= 2
            uialert(app.UIFigure, "Mode Compare : coche exactement 2 lignes dans la colonne 'Compare'.", "Proceed");
            return;
        end

        gapROI     = max(1, round(args.BarHeight/2));  % espace entre ROIs
        gapCompare = max(1, round(args.BarHeight/4));  % espace entre A et B dans une ROI


       batonnets_proceedRender(roiList, compareKeys, ...
    'BarHeight', args.BarHeight, ...
    'FrameWidth', args.FrameWidth, ...
    'ShowColorbar', args.ShowColorbar, ...
    'LabelColormapName', args.LabelColormapName, ...
    'NumbersColormapName', args.NumbersColormapName, ...
    'DisplayMaxTraj', app.DisplayMaxtrajEditField.Value, ...
    'EventRulesByKey', eventRulesByKey, ...
    'Compare', true, ...
    'GapROI', gapROI, ...
    'GapCompare', gapCompare, ...
    'Classifier', classif);


    else
        % mode normal : on affiche ce qui est en Display (ou Compare, mais ici il n’y en a pas)
        if isempty(displayKeys)
            close(d);
            uialert(app.UIFigure, "Aucune dataserie cochée en 'Display' (ou 'Compare').", "Proceed");
            return;
        end

       batonnets_proceedRender(roiList, displayKeys, ...
    'BarHeight', args.BarHeight, ...
    'FrameWidth', args.FrameWidth, ...
    'ShowColorbar', args.ShowColorbar, ...
    'LabelColormapName', args.LabelColormapName, ...
    'DisplayMaxTraj', app.DisplayMaxtrajEditField.Value, ...
    'NumbersColormapName', args.NumbersColormapName, ...
    'Classifier', classif);
    end

    close(d);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
        end

        % Value changed function: ShowcolorbarCheckBox
        function ShowcolorbarCheckBoxValueChanged(app, event)
            value = app.ShowcolorbarCheckBox.Value;
            
        end

        % Button pushed function: AddRuleButton
        function AddRuleButtonPushed(app, event)

            
   if app.IsUpdating, return; end

    roiRow = app.getCurrentROIRow();
    if isnan(roiRow) || isempty(app.CurrentEventKey), return; end

    % récupérer les items actuels des popups
    fmt = app.UIEventTable.ColumnFormat;
    if numel(fmt) < 4 || ~iscell(fmt{3}) || isempty(fmt{3})
        itemsCls = {'<none>'};
    else
        itemsCls = fmt{3};
        if isempty(itemsCls), itemsCls = {'<none>'}; end
    end
    itemsCls = itemsCls(:)'; % row cellstr
    defaultCls = itemsCls{1};

    % data actuelle
    data = app.UIEventTable.Data;
    if isempty(data), data = cell(0,4); end

    % nouvelle ligne (jamais vide pour popup)
    newRow = {'Event', 'Start', defaultCls, defaultCls};
    data(end+1,:) = newRow;

    % sanitize + push
    app.IsUpdating = true;
    data = app.sanitizeEventTableData_(data, itemsCls);
    app.UIEventTable.Data = data;
    app.IsUpdating = false;

    % backend
    app.saveEventRulesForCurrentKey_();


        end

        % Button pushed function: RemoveRuleButton
        function RemoveRuleButtonPushed(app, event)
     
            
            if app.IsUpdating, return; end

    sel = app.UIEventTable.Selection;
    if isempty(sel), return; end

    data = app.UIEventTable.Data;
    if isempty(data), return; end

    r = sel(1);
    if r < 1 || r > size(data,1), return; end

    % enlever la ligne
    data(r,:) = [];

    % récupérer les items popup courants
    fmt = app.UIEventTable.ColumnFormat;
    if numel(fmt) < 4 || ~iscell(fmt{3}) || isempty(fmt{3})
        itemsCls = {'<none>'};
    else
        itemsCls = fmt{3};
        if isempty(itemsCls), itemsCls = {'<none>'}; end
    end
    itemsCls = itemsCls(:)';

    % sanitize + push (et clear selection pour éviter glitches)
    app.IsUpdating = true;
    app.UIEventTable.Selection = [];
    data = app.sanitizeEventTableData_(data, itemsCls);
    app.UIEventTable.Data = data;
    app.IsUpdating = false;

    % backend
    app.saveEventRulesForCurrentKey_();
        end

        % Cell edit callback: UIEventTable
        function UIEventTableCellEdit(app, event)
             if app.IsUpdating, return; end

    % Backend only
    app.saveEventRulesForCurrentKey_();
    app.propagateRulesIfCompare_();
            
        end

        % Selection changed function: UIEventTable
        function UIEventTableSelectionChanged(app, event)
            selection = app.UIEventTable.Selection;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIROITable = uitable(app.UIFigure);
            app.UIROITable.ColumnName = {'Select'; 'Project'; 'Classifier or Position (FOV)'; 'Select ROIs'};
            app.UIROITable.RowName = {};
            app.UIROITable.ColumnEditable = [true false false true];
            app.UIROITable.CellEditCallback = createCallbackFcn(app, @UIROITableCellEdit, true);
            app.UIROITable.SelectionChangedFcn = createCallbackFcn(app, @UIROITableSelectionChanged, true);
            app.UIDataseriesTable = uitable(app.UIFigure);
            app.UIDataseriesTable.ColumnName = {'Display'; 'Compare'; 'Dataseries'; 'Data'; 'Type'};
            app.UIDataseriesTable.RowName = {};
            app.UIDataseriesTable.ColumnEditable = [true true false false false];
            app.UIDataseriesTable.CellEditCallback = createCallbackFcn(app, @UIDataseriesTableCellEdit, true);
            app.UIDataseriesTable.SelectionChangedFcn = createCallbackFcn(app, @UIDataseriesTableSelectionChanged, true);
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TrajheightEditFieldLabel = uilabel(app.UIFigure);
            app.TrajheightEditFieldLabel.Text = 'Traj height';
            app.TrajheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrajheightEditField.Value = 10;
            app.TrajframewidthEditFieldLabel = uilabel(app.UIFigure);
            app.TrajframewidthEditFieldLabel.Text = 'Traj frame width';
            app.TrajframewidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrajframewidthEditField.Value = 2;
            app.ShowcolorbarCheckBox = uicheckbox(app.UIFigure);
            app.ShowcolorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowcolorbarCheckBoxValueChanged, true);
            app.ShowcolorbarCheckBox.Text = 'Show colorbar';
            app.ShowcolorbarCheckBox.Value = true;
            app.LabelcolorbarEditFieldLabel = uilabel(app.UIFigure);
            app.LabelcolorbarEditFieldLabel.Text = 'Label colormap';
            app.LabelcolormapEditField = uieditfield(app.UIFigure, 'text');
            app.LabelcolormapEditField.Value = 'lines';
            app.LabelcolorbarEditField_2Label = uilabel(app.UIFigure);
            app.LabelcolorbarEditField_2Label.Text = 'Numbers colormap';
            app.NumberscolormapEditField = uieditfield(app.UIFigure, 'text');
            app.NumberscolormapEditField.Value = 'jet';
            app.DisplayMaxtrajEditFieldLabel = uilabel(app.UIFigure);
            app.DisplayMaxtrajEditFieldLabel.Text = 'Display Max traj';
            app.DisplayMaxtrajEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplayMaxtrajEditField.Value = 20;
            app.UIEventTable = uitable(app.UIFigure);
            app.UIEventTable.ColumnName = {'Name'; 'Type'; 'Class 1'; 'Class 2'};
            app.UIEventTable.RowName = {};
            app.UIEventTable.ColumnEditable = [true true true true];
            app.UIEventTable.CellEditCallback = createCallbackFcn(app, @UIEventTableCellEdit, true);
            app.UIEventTable.SelectionChangedFcn = createCallbackFcn(app, @UIEventTableSelectionChanged, true);
            app.AddRuleButton = uibutton(app.UIFigure, 'push');
            app.AddRuleButton.ButtonPushedFcn = createCallbackFcn(app, @AddRuleButtonPushed, true);
            app.AddRuleButton.Text = 'Add Rule';
            app.RemoveRuleButton = uibutton(app.UIFigure, 'push');
            app.RemoveRuleButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRuleButtonPushed, true);
            app.RemoveRuleButton.Text = 'Remove Rule';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIclassiMismatchGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end