classdef ROIextracterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIidentifierUIFigure          matlab.ui.Figure
        PositionlistPanel              matlab.ui.container.Panel
        GenerateROIsButton             matlab.ui.control.Button
        DeselectallButton              matlab.ui.control.Button
        SelectallButton                matlab.ui.control.Button
        TestonselectedpositionsButton  matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        ROIDetectionparametersPanel    matlab.ui.container.Panel
        channelnameEditField           matlab.ui.control.EditField
        channelnameEditFieldLabel      matlab.ui.control.Label
        PatternLabel                   matlab.ui.control.Label
        SetreferencepatternButton      matlab.ui.control.Button
        test                           matlab.ui.control.Button
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ReferenceframeEditField        matlab.ui.control.NumericEditField
        ReferenceframeEditFieldLabel   matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        Data % Description
    end

    methods (Access = private)

        function updateTable(app)
            shallowObj=app.Data.shallowObj;

            t=app.UITable;
            t.ColumnEditable=[true false false false false false false];
            t.ColumnWidth={50 50 'Auto' 90 90 80 80 80};
            t.ColumnName={'Select' 'Index' 'Position Name' 'current ROIs' 'new ROIs' 'Crop' 'Pattern'};


            if numel(t.Data)
            selectedfordetection=cellfun(@(x) x==1,t.Data(:,1));
            else
            selectedfordetection=logical(ones(1,numel(shallowObj.fov)));
            end

            for k=1:numel(shallowObj.fov)
                if numel(numel(shallowObj.fov(k).roi)) & numel(shallowObj.fov(k).roi(1).id)==0
                    n=0;
                else
                    n=numel(shallowObj.fov(k).roi);
                end

                m=false;
                if numel(shallowObj.fov(k).crop)
                    m=true;
                end

                p=false;
                if numel(shallowObj.fov(k).pattern)
                    p=true;
                end

                z=app.Data.detectedrois(k);

                Data(k,:)={selectedfordetection(k) num2str(k) shallowObj.fov(k).id n z m p};
            end

            % Data

            t.Data=Data;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fovobj, refresh)
    % app.callingApp=callingApp;
    % app.Data.shallowObj=shallowObj;
       %  findPattern(app);

       if nargin==1 
           refresh=[];

           warndlg('Please provide a FOV object as an argument !');
           delete(app);
          return;
       end

       app.Data.fovobj=fovobj;


       patt=0;
      if numel(fovobj)
          if numel(fovobj.pattern)==0
              % pattenr does not exist
              patt=0;
              app.PatternLabel.Text=['No pattern found in FOV ' fovobj.id '!'];
          else
               patt=1;
               app.PatternLabel.Text=['Pattern found in FOV ' fovobj.id '; Size : ' num2str(fovobj.pattern(3)) 'x' num2str(fovobj.pattern(4))];
          end
      else
        % look for pattern in other fov in the same project ? 
        %do it later
        app.PatternLabel.Text=['No pattern found; First set pattern in image!'];
      end


      pix=find(fovobj.display.selectedchannel,1,'first');
      app.channelnameEditField.Value=fovobj.channel{pix};

  
        if numel(fovobj)  
        app.Data.shallowObj= fovobj.flaggedROIs;

        for i=1:numel(app.Data.shallowObj.fov)
            
           if numel(app.Data.shallowObj.fov(i).roi)==1 && numel(app.Data.shallowObj.fov(i).roi.id)==0
                    z=0;
           else
                    z=numel(app.Data.shallowObj.fov(i).roi);
           end
            app.Data.detectedrois(i)=z;
        end

        updateTable(app)
        end


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
          %  uiresume(app.callingApp.DetecDivUIFigure)
           delete(app)
        end

        % Close request function: ROIidentifierUIFigure
        function ROIidentifierUIFigureCloseRequest(app, event)
            %uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)

        end

        % Value changed function: ReferenceframeEditField
        function ReferenceframeEditFieldValueChanged(app, event)
         %   value = app.ReferenceframeEditField.Value;
          %  shallowObj=app.Data.shallowObj;

          %  [cc,i,k]=computePattern(app,shallowObj,value);
          %  displayPattern(app,shallowObj);
        end

        % Button pushed function: TestonselectedpositionsButton
        function TestonselectedpositionsButtonPushed(app, event)
            
               if numel(app.Data.fovobj.pattern)==0
                warndld('Pattern is not defined!')
                return;
            end
           
            
            shallowObj=app.Data.shallowObj;

            if numel(app.UITable.Data)

                selectedfordetection=find(cellfun(@(x) x==1,app.UITable.Data(:,1)));
                frameid=app.ReferenceframeEditField.Value;
                thr=app.ThresholdEditField.Value;
                str=app.channelnameEditField.Value;
            pix=find(matches(app.Data.fovobj.channel,str));

            for i=1:numel(selectedfordetection)
                sel=selectedfordetection(i);
                fovs(i)=shallowObj.fov(sel);
            end

tmp=readImage(app.Data.fovobj,frameid,pix);
 pattern=app.Data.fovobj.pattern;
 pattimg=tmp(pattern(2):pattern(2)+pattern(4),pattern(1):pattern(1)+pattern(3));

 out=identifyROIs('FOV',fovs,'Frames',frameid,'Test','Threshold',thr,'Pattern',pattimg,'Crop',app.Data.fovobj.crop,'Channel',pix);

 cc=1;
 for i=selectedfordetection
 app.Data.detectedrois(i)=size(out(cc).scaled,1);
 cc=cc+1;
end
               % sha
               % llowObj.identifyROIs('FOV',selectedfordetection','Frames',frameid,'Threshold',thr,);

                %uialert(app.ROIidentifierUIFigure,'ROI detection is complete !','Success','Icon','success');
                updateTable(app);
            else
                uialert(app.ROIidentifierUIFigure,'There is no ROI in the table!','Warning')
            end
        end

        % Button pushed function: test
        function testButtonPushed(app, event)
            
            if numel(app.Data.fovobj.pattern)==0
                warndld('Pattern is not defined!')
                return;
            end
            
            frameid=app.ReferenceframeEditField.Value;
            thr=app.ThresholdEditField.Value;

            str=app.channelnameEditField.Value;
            pix=find(matches(app.Data.fovobj.channel,str));

            tmp=readImage(app.Data.fovobj,frameid,pix);
             pattern=app.Data.fovobj.pattern;
            pattimg=tmp(pattern(2):pattern(2)+pattern(4),pattern(1):pattern(1)+pattern(3));

            out=identifyROIs('FOV',app.Data.fovobj,'Frames',frameid,'Test','Threshold',thr,'Pattern',pattimg,'Crop',app.Data.fovobj.crop,'Channel',pix);

           scaled2=out(1).scaled;

            h=app.Data.fovobj.view(frameid);

            test=findobj('Tag','roitesttag');
            if numel(test)
                if ishandle(test)
                    delete(test);
                end
            end

            ax=findobj(h,'type','Axes');

                 for kk=1:size(scaled2,1)
            roitmp=scaled2(kk,:);
            roitmp=[roitmp(1) roitmp(2) roitmp(1)+ roitmp(3) roitmp(2)+ roitmp(4)];
            htest(kk)=patch(ax(1),'XData',[roitmp(1) roitmp(3) roitmp(3) roitmp(1) roitmp(1)],'YData',[roitmp(2) roitmp(2) roitmp(4) roitmp(4) roitmp(2)],'FaceAlpha',0,'FaceColor',[0 0 0],'EdgeColor',[1 0 1],'Tag','roitesttag','UserData',i);
                 end

           
                 figure(h);

        end

        % Button pushed function: SetreferencepatternButton
        function SetreferencepatternButtonPushed(app, event)
            if numel(app.Data.fovobj)
                h=app.Data.fovobj.view;
                figure(h);
            end
 
        end

        % Button pushed function: GenerateROIsButton
        function GenerateROIsButtonPushed(app, event)
               if numel(app.Data.fovobj.pattern)==0
                errordlg('Pattern is not defined!')
                return;
               end

            
            shallowObj=app.Data.shallowObj;

            if numel(app.UITable.Data)

                selectedfordetection=find(cellfun(@(x) x==1,app.UITable.Data(:,1)));
                frameid=app.ReferenceframeEditField.Value;
                thr=app.ThresholdEditField.Value;
                str=app.channelnameEditField.Value;
            pix=find(matches(app.Data.fovobj.channel,str));

            n=0;
            fovs=fov; 

            if numel(selectedfordetection)==0
                   errordlg('No position selected !')
                return;
            end

            for i=1:numel(selectedfordetection)

                j=selectedfordetection(i);

                fovs(i)=shallowObj.fov(j);

       
                if numel(numel(shallowObj.fov(j).roi)) & numel(shallowObj.fov(j).roi(1).id)==0
                    n=0;
                else
                    n=numel(shallowObj.fov(j).roi);
                end
                
                if n==1 
                    break;
                end
            end

            if n>0
            sel=uiconfirm(app.ROIidentifierUIFigure,'Some ROIs exist already, overwrite?','Confirm ROI Creation');
            switch sel 
                case 'OK'

                case 'Cancel'
                    return;
            end
            end

 tmp=readImage(app.Data.fovobj,frameid,pix);
 pattern=app.Data.fovobj.pattern;
 pattimg=tmp(pattern(2):pattern(2)+pattern(4),pattern(1):pattern(1)+pattern(3));
 

 out=identifyROIs('FOV',fovs,'Frames',frameid,'Threshold',thr,'Pattern',pattimg,'Crop',app.Data.fovobj.crop,'Channel',pix);


 for i=1:numel(out)
      j=out(i).fovid;
      app.Data.detectedrois(j)=size(out(i).scaled,1);
end
               % sha
               % llowObj.identifyROIs('FOV',selectedfordetection','Frames',frameid,'Threshold',thr,);

                %uialert(app.ROIidentifierUIFigure,'ROI detection is complete !','Success','Icon','success');
                updateTable(app);

                h=app.Data.fovobj.view(frameid);
                figure(h);
            else
                uialert(app.ROIidentifierUIFigure,'There is no ROI in the table!','Warning')
            end

        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
                n=size(app.UITable.Data,1);
            t=cell(1,n);
            t(:)={false};
  
            app.UITable.Data(:,1)=t';
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
             n=size(app.UITable.Data,1);
            t=cell(1,n);
            t(:)={true};
  
            app.UITable.Data(:,1)=t';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIidentifierUIFigure = uifigure('Visible', 'off');
            app.ROIidentifierUIFigure.Name = 'ROI identifier';
            app.ROIidentifierUIFigure.CloseRequestFcn = createCallbackFcn(app, @ROIidentifierUIFigureCloseRequest, true);
            app.ROIidentifierUIFigure.Tag = 'ROIExtracter';
            app.CloseButton = uibutton(app.ROIidentifierUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ROIDetectionparametersPanel = uipanel(app.ROIidentifierUIFigure);
            app.ROIDetectionparametersPanel.Title = 'ROI Detection parameters';
            app.ReferenceframeEditFieldLabel = uilabel(app.ROIDetectionparametersPanel);
            app.ReferenceframeEditFieldLabel.Text = 'Reference frame';
            app.ReferenceframeEditField = uieditfield(app.ROIDetectionparametersPanel, 'numeric');
            app.ReferenceframeEditField.ValueChangedFcn = createCallbackFcn(app, @ReferenceframeEditFieldValueChanged, true);
            app.ReferenceframeEditField.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.ROIDetectionparametersPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ROIDetectionparametersPanel, 'numeric');
            app.ThresholdEditField.Value = 0.5;
            app.test = uibutton(app.ROIDetectionparametersPanel, 'push');
            app.test.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.test.Text = 'Test ROI detection';
            app.SetreferencepatternButton = uibutton(app.ROIDetectionparametersPanel, 'push');
            app.SetreferencepatternButton.ButtonPushedFcn = createCallbackFcn(app, @SetreferencepatternButtonPushed, true);
            app.SetreferencepatternButton.Text = 'Set reference pattern...';
            app.PatternLabel = uilabel(app.ROIDetectionparametersPanel);
            app.PatternLabel.Text = 'Pattern';
            app.channelnameEditFieldLabel = uilabel(app.ROIDetectionparametersPanel);
            app.channelnameEditFieldLabel.Text = 'channel name:';
            app.channelnameEditField = uieditfield(app.ROIDetectionparametersPanel, 'text');
            app.channelnameEditField.Editable = 'off';
            app.PositionlistPanel = uipanel(app.ROIidentifierUIFigure);
            app.PositionlistPanel.Title = 'Position list ';
            app.UITable = uitable(app.PositionlistPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'If one single cropping area exists, it will be used for all position; if none exists, no cropping will be done; '};
            app.TestonselectedpositionsButton = uibutton(app.PositionlistPanel, 'push');
            app.TestonselectedpositionsButton.ButtonPushedFcn = createCallbackFcn(app, @TestonselectedpositionsButtonPushed, true);
            app.TestonselectedpositionsButton.Text = 'Test on selected positions';
            app.SelectallButton = uibutton(app.PositionlistPanel, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.PositionlistPanel, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.GenerateROIsButton = uibutton(app.PositionlistPanel, 'push');
            app.GenerateROIsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateROIsButtonPushed, true);
            app.GenerateROIsButton.Text = 'Generate ROIs';
            % Show the figure after all components are created
            app.ROIidentifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIextracterGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ROIidentifierUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ROIidentifierUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIidentifierUIFigure)
        end
    end
end