classdef addDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AddselectedpositionstoprojectButton  matlab.ui.control.Button
        DataselectioncommandsPanel    matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        ParsingcommentsLabel          matlab.ui.control.Label
        filenameLabel                 matlab.ui.control.Label
        SamplefilenameLabel           matlab.ui.control.Label
        StacksfilterstringEditField   matlab.ui.control.EditField
        StacksfilterstringEditFieldLabel  matlab.ui.control.Label
        ChannelfilterstringEditField  matlab.ui.control.EditField
        ChannelfilterstringEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SelectdatadirectoryPanel      matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        ChoosedirectoryButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        project % Description
        output=[]; % Description
        com % Description
        mainapp % Description
    end
    
    methods (Access = private)
        
        function displayresults(app,pathe,update)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
        'Message','Parsing files in the selected directory...');
            d.Value=0.33;
            
            app.Label.Text=pathe;
            
            switch nargin
                case 2
                   % 'ok'
            app.output=parseInputData(pathe,'progress',d); % parses data 
        %   aa= app.output.pos(1)
                case 3
                 %  kk= app.StacksfilterstringEditField.Value
                    if numel(app.StacksfilterstringEditField.Value)==0
                        s={''};
                    else
                       s={app.StacksfilterstringEditField.Value}; 
                    end
                     if numel(app.ChannelfilterstringEditField.Value)==0
                        c={''};
                    else
                       c={app.ChannelfilterstringEditField.Value}; 
                     end
                    
            app.output=parseInputData(pathe,'progress',d,'channelfilter',c,'stackfilter',s);
            % parses data       
            
            end
            
            d.Message='Displaying results...';
            pause(0.5);
            
            if app.output.data==true
            plotresults(app);
            else
            app.Label_2.Text=app.output.comments;   
            end
            close(d); 
        end
        
        function plotresults(app)
            % display the result of parsing in the bottom panel 
            app.Label_2.Text=app.output.comments;
            app.UITable.Enable='on';
            
           
            tmp={};
            for i=1:numel(app.output.pos) 
                tmp{i,1}=true;
                tmp{i,2}=app.output.pos(i).name;
                tmp{i,3}=app.output.pos(i).channels;
                tmp{i,4}=num2str(app.output.pos(i).frames);
            end
            
          %  tmp
            app.UITable.Data=tmp;
            ncols = size(app.UITable.Data,2);
            app.UITable.ColumnEditable = true(1,ncols); %all columns editable
            app.UITable.ColumnEditable(2:4) = false; %column2 non-editable
            
            app.ChannelfilterstringEditField.Visible=true;
            app.StacksfilterstringEditField.Visible=true;
            
           app.ChannelfilterstringEditField.Value=app.output.pos(1).channelfilter{1};
           app.StacksfilterstringEditField.Value=app.output.pos(1).stackfilter{1};
           
           if numel(app.output.pos(1).filelist)
           app.filenameLabel.Text=app.output.pos(1).filelist{1}.name;
           end
           
           % app.filenameLabel.Text=datetime;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj, mainapp)
            app.project=shallowObj;
            
            if nargin==3
            app.mainapp=mainapp;
            end
        end

        % Button pushed function: ChoosedirectoryButton
        function ChoosedirectoryButtonPushed(app, event)
           
            pathe = uigetdir(pwd,'Select directory with data:');
    
            if pathe==0
                disp('Quit!');
            return;
            end
            
          %  app.project.tag='ok'
            displayresults(app,pathe);


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;

           app.ChannelfilterstringEditField.Value=app.output.pos(indices(1)).channelfilter{1};
           app.StacksfilterstringEditField.Value=app.output.pos(indices(1)).stackfilter{1};
           
           app.filenameLabel.Text=app.output.pos(indices(1)).filelist{1}.name;
           app.com=indices(1);
           
           %app.ChannelfilterstringEditField.Value=app.output.pos(app.com).channelfilter;
           %app.StacksfilterstringEditField.Value=app.output.pos(app.com).stackfilter;
        end

        % Value changed function: ChannelfilterstringEditField
        function ChannelfilterstringEditFieldValueChanged(app, event)
            value = app.ChannelfilterstringEditField.Value;
            stackvalue=app.StacksfilterstringEditField.Value;
            %app.output.pos(app.com).channelfilter={value};
            
            % process position again
            pathe=app.Label.Text;
            displayresults(app,pathe,'update');
        end

        % Value changed function: StacksfilterstringEditField
        function StacksfilterstringEditFieldValueChanged(app, event)
            value = app.ChannelfilterstringEditField.Value;
            stackvalue=app.StacksfilterstringEditField.Value;
            %app.output.pos(app.com).channelfilter={value};
            
            % process position again
            pathe=app.Label.Text;
            displayresults(app,pathe,'update');
        end

        % Value changing function: ChannelfilterstringEditField
        function ChannelfilterstringEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.UITable.Enable='off';
        end

        % Value changing function: StacksfilterstringEditField
        function StacksfilterstringEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.UITable.Enable='off';
        end

        % Callback function
        function UIFigureButtonDown(app, event)
          
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %plotresults(app);
        end

        % Button pushed function: 
        % AddselectedpositionstoprojectButton
        function AddselectedpositionstoprojectButtonPushed(app, event)
            if strcmp(app.AddselectedpositionstoprojectButton.Text,'Close')
                %app.mainapp.gatherVarsFromWorkspace;
                %app.mainapp.displayNodes;
                uiresume(app.mainapp.DetecDivUIFigure)
                app.delete;
                return
            end
            
            
            if numel(app.UITable.Data)==0
                uialert(app.UIFigure,'There are no data to be added !','Error')
                return;
            end
            
            selected=find(cellfun(@(x) x==1,app.UITable.Data(:,1)));
            
            if numel(selected)==0
                uialert(app.UIFigure,'You must select at least one item !','Warning');
                return;
            end
            
            app.output.pos=app.output.pos(selected);
            
            if numel(app.output)
            app.project.addData(app.output)
            app.AddselectedpositionstoprojectButton.Text='Close';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %'ok'
            uiresume(app.mainapp.DetecDivUIFigure)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.SelectdatadirectoryPanel = uipanel(app.UIFigure);
            app.SelectdatadirectoryPanel.Title = 'Select data directory';
            app.ChoosedirectoryButton = uibutton(app.SelectdatadirectoryPanel, 'push');
            app.ChoosedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosedirectoryButtonPushed, true);
            app.ChoosedirectoryButton.Text = 'Choose directory....';
            app.Label = uilabel(app.SelectdatadirectoryPanel);
            app.Label.Text = '';
            app.DataselectioncommandsPanel = uipanel(app.UIFigure);
            app.DataselectioncommandsPanel.Title = 'Data selection commands';
            app.UITable = uitable(app.DataselectioncommandsPanel);
            app.UITable.ColumnName = {'Select'; 'Position'; '# Channels'; '#Frames'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Enable = 'off';
            app.ChannelfilterstringEditFieldLabel = uilabel(app.DataselectioncommandsPanel);
            app.ChannelfilterstringEditFieldLabel.Text = 'Channel filter string';
            app.ChannelfilterstringEditField = uieditfield(app.DataselectioncommandsPanel, 'text');
            app.ChannelfilterstringEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelfilterstringEditFieldValueChanged, true);
            app.ChannelfilterstringEditField.ValueChangingFcn = createCallbackFcn(app, @ChannelfilterstringEditFieldValueChanging, true);
            app.StacksfilterstringEditFieldLabel = uilabel(app.DataselectioncommandsPanel);
            app.StacksfilterstringEditFieldLabel.Text = 'Stacks filter string';
            app.StacksfilterstringEditField = uieditfield(app.DataselectioncommandsPanel, 'text');
            app.StacksfilterstringEditField.ValueChangedFcn = createCallbackFcn(app, @StacksfilterstringEditFieldValueChanged, true);
            app.StacksfilterstringEditField.ValueChangingFcn = createCallbackFcn(app, @StacksfilterstringEditFieldValueChanging, true);
            app.SamplefilenameLabel = uilabel(app.DataselectioncommandsPanel);
            app.SamplefilenameLabel.Text = 'Sample file name: ';
            app.filenameLabel = uilabel(app.DataselectioncommandsPanel);
            app.filenameLabel.Text = 'filename';
            app.ParsingcommentsLabel = uilabel(app.DataselectioncommandsPanel);
            app.ParsingcommentsLabel.Text = 'Parsing comments:';
            app.Label_2 = uilabel(app.DataselectioncommandsPanel);
            app.Label_2.Text = '';
            app.AddselectedpositionstoprojectButton = uibutton(app.UIFigure, 'push');
            app.AddselectedpositionstoprojectButton.ButtonPushedFcn = createCallbackFcn(app, @AddselectedpositionstoprojectButtonPushed, true);
            app.AddselectedpositionstoprojectButton.Text = 'Add selected positions to project';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addDataGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end