classdef annotationBoundsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RulesPanel                      matlab.ui.container.Panel
        ChooseboundListBox              matlab.ui.control.ListBox
        ChooseboundListBoxLabel         matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        OffsettheboundbyEditField       matlab.ui.control.NumericEditField
        OffsettheboundbyEditFieldLabel  matlab.ui.control.Label
        occurenceoftheselectedeventtype0ifyouwantthelasteventLabel  matlab.ui.control.Label
        SettheboundtotheEditField       matlab.ui.control.NumericEditField
        SettheboundtotheEditFieldLabel  matlab.ui.control.Label
        ValueListBox                    matlab.ui.control.ListBox
        ValueListBoxLabel               matlab.ui.control.Label
        DatasetListBox                  matlab.ui.control.ListBox
        DatasetListBoxLabel             matlab.ui.control.Label
        DataseriesListBox               matlab.ui.control.ListBox
        DataseriesListBoxLabel          matlab.ui.control.Label
        ChooseButtonGroup               matlab.ui.container.ButtonGroup
        UseuserdefinedboundsforeachROIButton  matlab.ui.control.RadioButton
        ValuesminmaxEditField           matlab.ui.control.NumericEditField
        ValuesminmaxEditFieldLabel      matlab.ui.control.Label
        BoundsbasedonrulesbelowButton   matlab.ui.control.RadioButton
        ApplyboundtoallframesempyallframesButton  matlab.ui.control.RadioButton
        CloseButton                     matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function  display(app)

            classif=app.Data.classiObj;

            val=classif.bounds.Type;

            if strcmp(val,'Auto')
                app.UseuserdefinedboundsforeachROIButton.Value=true;

            end

            if strcmp(val,'Manual')
                app.ApplyboundtoallframesempyallframesButton.Value=true;
            end
            
             if strcmp(val,'Rules')
                app.BoundsbasedonrulesbelowButton.Value=true;
                 app.RulesPanel.Visible="on";
             else
                 app.RulesPanel.Visible="off";
             end

            if app.ChooseboundListBox.Value=="Min bound"
                val_sel=1;
            else
                val_sel=2;
            end

            if numel(classif.bounds.Rules)<val_sel
                 classif.bounds.Rules(2)=  classif.bounds.Rules(1);
            end

            val_dataseries= classif.bounds.Rules.Dataseries{val_sel}
             

            listData=app.Data.listData; 
            app.DataseriesListBox.Items=listData; 

            if numel(classif.bounds.Rules.Dataseries)
                
            else

            end
         

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classif)


            listData={};

            for i=1:numel(classif.roi)
                listData=[listData {classif.roi(i).data.groupid}];
            end

            listData=unique(listData);
            
            % HERE : fix the list 

            app.Data.listData=listData;

            app.Data.classiObj=classif;
             display(app)
        end

        % Selection changed function: ChooseButtonGroup
        function ChooseButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseButtonGroup.SelectedObject;
            
            if selectedButton.Text=="Bounds based on rules below"
                    app.RulesPanel.Visible="on";
                    app.Data.classiObj.bounds.Type='Rules';
            else
                    app.RulesPanel.Visible="off";
            end

              if selectedButton.Text=="Apply bound to all frames (empy:all frames)"
                     app.Data.classiObj.bounds.Type='Auto';
              end

               if selectedButton.Text=="Use user-defined bounds for each ROI"
                     app.Data.classiObj.bounds.Type='Manual';
              end


        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ChooseButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseButtonGroupSelectionChanged, true);
            app.ChooseButtonGroup.Title = 'Choose';
            app.ApplyboundtoallframesempyallframesButton = uiradiobutton(app.ChooseButtonGroup);
            app.ApplyboundtoallframesempyallframesButton.Text = 'Apply bound to all frames (empy:all frames)';
            app.BoundsbasedonrulesbelowButton = uiradiobutton(app.ChooseButtonGroup);
            app.BoundsbasedonrulesbelowButton.Text = 'Bounds based on rules below';
            app.BoundsbasedonrulesbelowButton.Value = true;
            app.ValuesminmaxEditFieldLabel = uilabel(app.ChooseButtonGroup);
            app.ValuesminmaxEditFieldLabel.Text = 'Values : min max';
            app.ValuesminmaxEditField = uieditfield(app.ChooseButtonGroup, 'numeric');
            app.UseuserdefinedboundsforeachROIButton = uiradiobutton(app.ChooseButtonGroup);
            app.UseuserdefinedboundsforeachROIButton.Text = 'Use user-defined bounds for each ROI';
            app.RulesPanel = uipanel(app.UIFigure);
            app.RulesPanel.Title = 'Rules';
            app.RulesPanel.Visible = 'off';
            app.DataseriesListBoxLabel = uilabel(app.RulesPanel);
            app.DataseriesListBoxLabel.Text = 'Dataseries';
            app.DataseriesListBox = uilistbox(app.RulesPanel);
            app.DatasetListBoxLabel = uilabel(app.RulesPanel);
            app.DatasetListBoxLabel.Text = 'Dataset';
            app.DatasetListBox = uilistbox(app.RulesPanel);
            app.ValueListBoxLabel = uilabel(app.RulesPanel);
            app.ValueListBoxLabel.Text = 'Value';
            app.ValueListBox = uilistbox(app.RulesPanel);
            app.SettheboundtotheEditFieldLabel = uilabel(app.RulesPanel);
            app.SettheboundtotheEditFieldLabel.Text = 'Set the bound to the ';
            app.SettheboundtotheEditField = uieditfield(app.RulesPanel, 'numeric');
            app.occurenceoftheselectedeventtype0ifyouwantthelasteventLabel = uilabel(app.RulesPanel);
            app.occurenceoftheselectedeventtype0ifyouwantthelasteventLabel.Text = 'th occurence of the selected event/value; type 0 if you want to seelct the last event';
            app.OffsettheboundbyEditFieldLabel = uilabel(app.RulesPanel);
            app.OffsettheboundbyEditFieldLabel.Text = 'Offset the bound by ';
            app.OffsettheboundbyEditField = uieditfield(app.RulesPanel, 'numeric');
            app.DropDown = uidropdown(app.RulesPanel);
            app.DropDown.Items = {'Occurence', 'Frame'};
            app.DropDown.Value = 'Occurence';
            app.ChooseboundListBoxLabel = uilabel(app.RulesPanel);
            app.ChooseboundListBoxLabel.Text = 'Choose bound  ';
            app.ChooseboundListBox = uilistbox(app.RulesPanel);
            app.ChooseboundListBox.Items = {'Min bound', 'Max bound'};
            app.ChooseboundListBox.Value = 'Min bound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotationBoundsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end