classdef classiRepository < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        classiRepositoryUIFigure   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SetrepositorypathMenu      matlab.ui.container.Menu
        RefreshclassifierlistMenu  matlab.ui.container.Menu
        ClassipathEditField        matlab.ui.control.EditField
        ClassipathEditFieldLabel   matlab.ui.control.Label
        Clone                      matlab.ui.control.Button
        SelectedclassifierPanel    matlab.ui.container.Panel
        Display                    matlab.ui.control.Button
        DownloadButton             matlab.ui.control.Button
        Open                       matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UITable                    matlab.ui.control.Table
        CancelButton               matlab.ui.control.Button
    end


    properties (Access = private)
        Data % Description
        CallingApp % Description
    end

    properties (Access = public)

    end

    methods (Access = public)

        function hFig = getCallingUIFigure(app)
    hFig = [];
    if ~isempty(app.CallingApp)
        if isprop(app.CallingApp, 'LifespanizerUIFigure') && ~isempty(app.CallingApp.LifespanizerUIFigure)
            hFig = app.CallingApp.LifespanizerUIFigure;
        elseif isprop(app.CallingApp, 'ClassifierUIFigure') && ~isempty(app.CallingApp.ClassifierUIFigure)
            hFig = app.CallingApp.ClassifierUIFigure;
        elseif isprop(app.CallingApp, 'UIFigure') && ~isempty(app.CallingApp.UIFigure)
            hFig = app.CallingApp.UIFigure;
        end
    end
        end

        function displayClassi(app)

          
    % load local classifiers
    pth = userpath;

    if ~exist(fullfile(pth,'Detecdiv'), 'dir')
        mkdir(fullfile(pth,'Detecdiv'));
    end

    fle = fullfile(pth, 'Detecdiv', 'repositoryPath.mat');

    repopth = [];

    % Charger proprement si le fichier existe
    if exist(fle,'file')
        S = load(fle);  % charge dans une struct
        if isfield(S,'repopth')
            repopth = S.repopth;
        end
    end

    % Normalisation du type
    if isstring(repopth)
        % garder un seul élément si c'est un string array
        repopth = repopth(1);
        repopth = char(repopth);   % on passe en char pour être sûr
    elseif iscell(repopth)
        % on prend le premier élément si cellstr
        repopth = repopth{1};
    end

    % Si repopth est vide, numérique ou n'est pas un dossier valide,
    % on recrée le chemin par défaut
    if isempty(repopth) || ~ischar(repopth) || ~isfolder(repopth)
        repopth = fullfile(pth, 'ClassiRepository');
        if ~isfolder(repopth)
            mkdir(repopth);
        end
        save(fle, 'repopth');  % on sauve le chemin nettoyé
    end

    app.Data.repopth = repopth;

    % Ici repopth est garanti être un char de dossier existant


            liste=dir(repopth);

            liste=liste([liste.isdir]);
            liste=liste(~contains({liste.name},{'..','.'}));

            %   liste=list(isfolder(liste

            t=app.UITable;

            %     t.ColumnName={'Se','Select for test',' ROI index','ROI Id'};
            t.ColumnWidth={100, 150, 'auto', 50, 100 };
            t.ColumnEditable=[false false false false false];
            d=t.Data;
            d={};

            cc=1;
  
            for i=1:numel(liste)
                % test is classi has the right architecture

                pth=fullfile(liste(i).folder, liste(i).name);
                clas=fullfile(pth,[liste(i).name '_classification.mat']);

                if exist(clas)
            
                    load(clas)

                    app.Data.path{cc}=clas;
                    descri=classiObj.description{2};
                    nroi=numel(classiObj.roi);
                    if numel(classiObj.roi(1).id)==0
                        nroi=0;
                    end
                    d{cc,1}=liste(i).name;
                    if ischar(classiObj.description{1})
                        str=classiObj.description{1};
                    else
                        str=classiObj.description{1}{1};
                    end
                    d{cc,2}=str;
                    d{cc,3}=descri;
                    d{cc,4}=num2str(nroi);
                    d{cc,5}='local';
                    cc=cc+1;

                    clear classiObj;
                end

            end

            % load remote classifiers

            fle=which('shallowLoad.m');
            [pth,  ~, ~]=fileparts(fle);

            di=fullfile(pth,'/supportfunction/paper/remoteClassifiers.mat');

            if exist(di)
                load(di);


                for i=1:size(remoteclassi,1)

                    d{cc,1}=remoteclassi{i,1};


                    d{cc,2}=remoteclassi{i,2};
                    d{cc,3}=remoteclassi{i,3};
                    d{cc,4}=num2str(remoteclassi{i,4});
                    d{cc,5}=' remote';
                    app.Data.path{cc}=remoteclassi{i,5};
                    cc=cc+1;
                end
            end


            app.UITable.Data=d;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)

            app.Data.image={};

            if nargin==2
                app.CallingApp=callingApp;
            else
                app.CallingApp=[];
            end

            app.DownloadButton.Enable='off';

            app.Open.Enable='off';
            app.Display.Enable='off';

            if nargin==2
                app.Clone.Visible='on';
            end

            displayClassi(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
     hCallingFig = getCallingUIFigure(app);
    if ~isempty(hCallingFig) && isvalid(hCallingFig)
        uiresume(hCallingFig);
    end
    delete(app);
        end

        % Menu selected function: SetrepositorypathMenu
        function SetrepositorypathMenuSelected(app, event)


            repopth=uigetdir(app.Data.repopth,'Input the location of the Classi Repostiory');

            app.Data.repopth=repopth;

            pth=userpath;
            fle= fullfile(pth,'Detecdiv/repositoryPath.mat');

            save(fle,'repopth');

            disp(['User selected : ' repopth ' for the classi repository']);

             displayClassi(app)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;

            if numel(selection)==0
                return
            end

            d=app.UITable.Data;

            typ=d{selection(1,1),5};

            if strcmp(typ,'local')
                app.DownloadButton.Enable='off';

                app.Open.Enable='on';
                app.Display.Enable='on';

            else
                app.DownloadButton.Enable='on';

                app.Open.Enable='off';
                app.Display.Enable='off';
            end

            loade=0;

       %      tmp=app.Data.image
               tt= app.Data.path{selection(1,1)};
               app.ClassipathEditField.Value=tt;
               
            if strcmp(d{selection(1,1),5},'local')

                if numel(app.Data.image)<selection(1,1)
                    loade=1;
                else
             %     tmp=  selection(1,1)
                    if numel(app.Data.image{selection(1,1)})==0
                        loade=1;
                    end
                end

                if loade==1
                    pth=app.Data.path{selection(1,1)};
                    [fld fle]=fileparts(pth);

                    sam=fullfile(fld,'sampleImage.png');

                    if exist(sam)
                        im=imread(sam);

                    else

                        classiObj=classiLoad(app.Data.path{selection(1,1)});

                        [ k, himg]=classiObj.displayFormattedTrainingSet('Display','Nimages',9);
                    %    himg
                        im=himg;
                        close
                        clear classiObj;
                    end
                    app.Data.image{selection(1,1)}=im;

                else
                    im=app.Data.image{selection(1,1)};
                end

            else
                im=[];
            end

            imshow(im,'parent',app.UIAxes);

         %   displayClassi(app);

        end

        % Button pushed function: Display
        function DisplayButtonPushed(app, event)
            selection = app.UITable.Selection;

            if numel(selection)==0
                return
            end

            %    d=app.UITable.Data;
            pth=app.Data.path{selection(1,1)};

            [fld fle]=fileparts(pth);

            sam=fullfile(fld,'sampleImage.png');

            if exist(sam)
                im=imread(sam);
                h=figure('Position',[100 100 800 600]);
                imshow(im);
                set(h,'Position',[100 100 800 600]);
            else

                classiObj=classiLoad(app.Data.path{selection(1,1)});

                %     classiObj
                [ k, himg]=classiObj.displayFormattedTrainingSet('Display','Nimages',9);

                clear classiObj;
            end

        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)
            selection = app.UITable.Selection;
            classiObj=classiLoad(app.Data.path{selection(1,1)});
            classifierGUI(classiObj);
        end

        % Button pushed function: Clone
        function CloneButtonPushed(app, event)
      selection = app.UITable.Selection;
    if ~isempty(selection)
        % Récupérer le handle de la figure appelante via notre fonction helper
        hCallingFig = getCallingUIFigure(app);
        if ~isempty(hCallingFig) && isvalid(hCallingFig)
            % Assigner le chemin du classifier sélectionné dans la propriété UserData
            hCallingFig.UserData = app.Data.path{selection(1,1)};
            uiresume(hCallingFig);
        end
        delete(app);
    else
        uialert(app.classiRepositoryUIFigure, 'Please select an item in the list !', 'Error');
    end
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
              selection = app.UITable.Selection;
            t=app.UITable.Data;

              if app.DownloadButton.Text=="Download"
                  
          
             url=  app.Data.path{selection(1,1)};

             fle=fullfile(app.Data.repopth, t{selection(1,1),1});
             
             if exist(fle) % folder already exist, therefore quit
                  msg=['A classifier with this name already exists in the repository! Please remove/rename that classifier first'];
                uialert(app.classiRepositoryUIFigure,msg,'Warning','Icon','error');
                return;
             end


            fle=fullfile(app.Data.repopth,[t{selection(1,1),1} '.zip']);

            app.SelectedclassifierPanel.Title= "Downloading remote classifier; Please wait....";
                  app.DownloadButton.Text="Cancel download";
                  pause(1);

             res=websave(fle,url);
             
              app.SelectedclassifierPanel.Title= "Unzipping archive in folder; Please wait....";
              pause(1);

             unzip(res,app.Data.repopth)

              
                app.SelectedclassifierPanel.Title= "Unzipping archive in folder; Please wait....";
                pause(0.5);
                displayClassi(app)
                 app.SelectedclassifierPanel.Title= "Selected classifier";
                app.DownloadButton.Text="Download";

                  displayClassi(app); % refreshes the list
                 delete(fle); % removes the zip file

              else

                 app.DownloadButton.Text="Download";
                 app.SelectedclassifierPanel.Title= "Selected classifier";
              end


        end

        % Menu selected function: RefreshclassifierlistMenu
        function RefreshclassifierlistMenuSelected(app, event)
            displayClassi(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.classiRepositoryUIFigure = uifigure('Visible', 'off');
            app.classiRepositoryUIFigure.Name = 'Classifier Repository';
            app.FileMenu = uimenu(app.classiRepositoryUIFigure);
            app.FileMenu.Text = 'File';
            app.SetrepositorypathMenu = uimenu(app.FileMenu);
            app.SetrepositorypathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetrepositorypathMenuSelected, true);
            app.SetrepositorypathMenu.Text = 'Set repository path...';
            app.RefreshclassifierlistMenu = uimenu(app.FileMenu);
            app.RefreshclassifierlistMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshclassifierlistMenuSelected, true);
            app.RefreshclassifierlistMenu.Text = 'Refresh classifier list';
            app.CancelButton = uibutton(app.classiRepositoryUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.UITable = uitable(app.classiRepositoryUIFigure);
            app.UITable.ColumnName = {'Name'; 'Type'; 'Description'; 'ROIs'; 'Status'};
            app.UITable.RowName = {};
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.SelectedclassifierPanel = uipanel(app.classiRepositoryUIFigure);
            app.SelectedclassifierPanel.Title = 'Selected classifier';
            app.UIAxes = uiaxes(app.SelectedclassifierPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Open = uibutton(app.SelectedclassifierPanel, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Tooltip = {'To clone this classifier, select ''New Classifier'' in the main detecdiv window and select ''Duplicate from repository'''};
            app.Open.Text = 'Classifier properties...';
            app.DownloadButton = uibutton(app.SelectedclassifierPanel, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Enable = 'off';
            app.DownloadButton.Text = 'Download';
            app.Display = uibutton(app.SelectedclassifierPanel, 'push');
            app.Display.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.Display.Visible = 'off';
            app.Display.Text = 'Display sample images';
            app.Clone = uibutton(app.classiRepositoryUIFigure, 'push');
            app.Clone.ButtonPushedFcn = createCallbackFcn(app, @CloneButtonPushed, true);
            app.Clone.Visible = 'off';
            app.Clone.Text = 'Select classifier';
            app.ClassipathEditFieldLabel = uilabel(app.classiRepositoryUIFigure);
            app.ClassipathEditFieldLabel.Text = 'Classi path:';
            app.ClassipathEditField = uieditfield(app.classiRepositoryUIFigure, 'text');
            app.ClassipathEditField.Editable = 'off';
            % Show the figure after all components are created
            app.classiRepositoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classiRepository(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.classiRepositoryUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.classiRepositoryUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.classiRepositoryUIFigure)
        end
    end
end