classdef classifierBKGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrainingrusselectionUIFigure  matlab.ui.Figure
        CurrentRunEditField           matlab.ui.control.EditField
        CurrentRunEditFieldLabel      matlab.ui.control.Label
        ShowparametersButton          matlab.ui.control.Button
        ShowtraininginfoButton        matlab.ui.control.Button
        ShowstatsButton               matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        RestoreselectedversioncloseButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Data=[]; % Description
        callingApp = []
    end
    
    properties (Access = public)
            Result = struct( ...
        'didRestore',false, ...
        'mode',"", ...
        'runDir',"", ...
        'trainingParam',[], ...
        'classes',{{}}, ...
        'channelName',{{}}, ...
        'refreshed',false);
    end
    
    methods (Access = private)
        function idx = localGetSelectedRow(app)

idx = [];
try
    sel = find(cellfun(@(x) isequal(x,true), app.UITable.Data(:,1)));
    if isempty(sel), return; end
    idx = sel(1);
catch
    idx = [];
end

        end

        function runDir = localGetSelectedRunDir(app)

idx = app.localGetSelectedRow();
runDir = "";

if isempty(idx) || ~isfield(app.Data,'runNames') || idx > numel(app.Data.runNames)
    return
end

runName = app.Data.runNames{idx};
runDir  = fullfile(app.Data.runsDir, runName);

end


function localShowTextFile(app, filePath, titleStr)

try
    txt = fileread(filePath);
catch ME
    uialert(app.TrainingrusselectionUIFigure, ME.message, 'Read failed');
    return
end

fig = uifigure('Name', sprintf('classifierBKGUI - %s', titleStr), ...
    'Position', [200 200 900 600]);

ta = uitextarea(fig, 'Position', [10 10 880 580]);
ta.Editable = 'off';
ta.Value = splitlines(string(txt));

end

function localShowMatFile(app, filePath, suggestedVarName)

try
    S = load(filePath);
catch ME
    uialert(app.TrainingrusselectionUIFigure, ME.message, 'Load failed');
    return
end

% If only 1 variable inside, expose it nicely
fn = fieldnames(S);
if numel(fn) == 1
    val = S.(fn{1});
    varName = matlab.lang.makeValidName(fn{1});
    assignin('base', varName, val);
    openvar(varName);
else
    % expose whole struct
    varName = matlab.lang.makeValidName("runMat");
    assignin('base', varName, S);
    openvar(varName);
end

end

function localSetShowButtonsEnabled(app, tf)

app.ShowtraininginfoButton.Enable          = matlab.lang.OnOffSwitchState(tf);
app.ShowparametersButton.Enable            = matlab.lang.OnOffSwitchState(tf);
app.ShowstatsButton.Enable                 = matlab.lang.OnOffSwitchState(tf);
app.RestoreselectedversioncloseButton.Enable = matlab.lang.OnOffSwitchState(tf);

end

function idx = localGetSelectedRowForShow(app)
% For "Show *" actions, we use the checkbox selection (column 1), not the mouse highlight.
idx = app.localGetSelectedRow();
end


function runDir = localGetRunDirFromRow(app, idx)

runDir = "";
if ~app.Data.isRunsMode, return; end
if isempty(idx) || idx<1 || idx>numel(app.Data.runNames), return; end

runName = app.Data.runNames{idx};
runDir  = fullfile(app.Data.runsDir, runName);

end


function idx = localGetSelectedRowForRestore(app)

idx = [];
try
    sel = find(cellfun(@(x) isequal(x,true), app.UITable.Data(:,1)));
    if isempty(sel), return; end
    idx = sel(1);
catch
    idx = [];
end

end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, classiObj)

% Robust startup for classifierBKGUI:
% - Prefer runs/ folders (new format)
% - Fallback to legacy classiObj.version()
% - Never leave the UI empty silently

% ---------------------------
% Init state / store inputs
% ---------------------------

disp("BKGUI: path = " + string(classiObj.path));
try
    [pth,file] = classiObj.getPath;
    disp("BKGUI: getPath pth = " + string(pth));
    disp("BKGUI: getPath file = " + string(file));
catch ME
    disp("BKGUI: getPath failed: " + string(ME.message));
end

app.Data = struct();
app.Data.classiObj = classiObj;
app.Data.isRunsMode = false;
app.Data.runsDir    = fullfile(classiObj.path,'runs');
app.Data.runNames   = {};

% ---- (1) show current run in the edit field ----
curRunStr = "";
try
    if isprop(classiObj,'run') || isfield(classiObj,'run')
        curRunStr = string(classiObj.run.runDir);
    end
catch
end

% best-effort pretty display: if it's a path, show last folder name
try
    if strlength(curRunStr)>0
        % detect path separators
        if contains(curRunStr, filesep) || contains(curRunStr, "/") || contains(curRunStr, "\")
            [~,bn,ext] = fileparts(char(curRunStr));
            curRunStr = string(bn) + string(ext);
        end
    end
catch
end

try

    app.CurrentRunEditField.Value = char(curRunStr);
    
catch
end

% Default UI state
app.localSetShowButtonsEnabled(false);

% Always start with an empty table (prevents stale content)
try
    app.UITable.Data = {};
catch
end

try
    runsDir = app.Data.runsDir;

    % ==========================================================
    % 1) Preferred: "runs" mode (folders under <path>/runs)
    % ==========================================================
    if isfolder(runsDir)

        dd = dir(runsDir);
        dd = dd([dd.isdir]);
        dd = dd(~ismember({dd.name},{'.','..'}));

        if ~isempty(dd)

            names = string({dd.name});

            % Keep folders that look like: yyyymmdd_HHMMSS or yyyymmdd-HHMMSS
            keep = ~cellfun('isempty', regexp(cellstr(names), '^\d{8}[_-]\d{6}', 'once'));
            dd = dd(keep);

            if ~isempty(dd)
                % Newest first
                [~,ord] = sort([dd.datenum],'descend');
                dd = dd(ord);

                sid = char(string(classiObj.strid));

                n = numel(dd);
                dat = cell(n,5);

                for i = 1:n
                    runName = dd(i).name;
                    runPath = fullfile(runsDir, runName);

                    % Date from folder timestamp (fallback)
                    dateStr = datestr(dd(i).datenum, 'yyyy-mm-dd HH:MM:SS');

                    % "Classification name" enriched from run.json if available
                    className = runName;
                    jsonFile = fullfile(runPath,'run.json');
                    if exist(jsonFile,'file')==2
                        try
                            txt = fileread(jsonFile);
                            J = jsondecode(txt);
                            if isfield(J,'timestamp') && ~isempty(J.timestamp)
                                dateStr = char(string(J.timestamp));
                            end
                            if isfield(J,'fun') && ~isempty(J.fun)
                                className = sprintf('%s (%s)', runName, char(string(J.fun)));
                            end
                            if isfield(J,'tag') && ~isempty(J.tag)
                                className = sprintf('%s [%s]', className, char(string(J.tag)));
                            end
                        catch
                            % ignore JSON errors
                        end
                    end

                    % # ROIs (best effort): from trainingParam.mat if present
                    nRois = NaN;
                    tpFile = fullfile(runPath,'trainingParam.mat');
                    if exist(tpFile,'file')==2
                        try
                            T = load(tpFile);
                            if isfield(T,'trainingParam')
                                tp = T.trainingParam;
                                if isfield(tp,'rois') && ~isempty(tp.rois)
                                    nRois = numel(tp.rois);
                                elseif isfield(tp,'Rois') && ~isempty(tp.Rois)
                                    nRois = numel(tp.Rois);
                                elseif isfield(tp,'trainingset') && ~isempty(tp.trainingset)
                                    nRois = numel(tp.trainingset);
                                end
                            end
                        catch
                        end
                    end

                    % Classifier artifact ALWAYS <sid>.mat inside run folder
                    clsFile = fullfile(runPath, [sid '.mat']);
                    if exist(clsFile,'file')==2
                        classifierName = [sid '.mat'];
                        dfi = dir(clsFile);
                        classifierDate = datestr(dfi.datenum,'yyyy-mm-dd HH:MM:SS');
                    else
                        classifierName = 'NOT FOUND';
                        classifierDate = '';
                    end

                    dat(i,:) = {false, className, dateStr, classifierName, classifierDate};
                end

                app.Data.isRunsMode = true;
                app.Data.runNames   = {dd.name}';

                app.UITable.Data = dat;
                app.UITable.ColumnName = {'Select'; 'Classification name'; 'Run Date'; '# ROIs'; 'Classifier Name'; 'Classifier Date'};
                app.UITable.ColumnWidth    = {50, 520, 180, 70, 160, 160};
                app.UITable.ColumnEditable = [true false false false false false];
                app.UITable.SelectionType  = 'row';

                % ---- preselect current run if it matches a folder name ----
                didPreselect = false;
                try
                    if strlength(curRunStr)>0
                        % curRunStr might already be runName; try direct match
                        ii = find(strcmp(app.Data.runNames, char(curRunStr)), 1, 'first');
                        if isempty(ii)
                            % also try contains match (in case curRunStr is longer)
                            ii = find(contains(string(app.Data.runNames), curRunStr), 1, 'first');
                        end
                        if ~isempty(ii)
                            app.UITable.Data(:,1) = {false};
                            app.UITable.Data(ii,1) = {true};
                            try, app.UITable.Selection = [ii 2]; catch, end
                            didPreselect = true;
                        end
                    end
                catch
                end

                % Buttons enabled only if a checkbox is ticked
                app.localSetShowButtonsEnabled(didPreselect && ~isempty(app.localGetSelectedRow()));
                return
            end
        end
    end

    % ==========================================================
    % 2) Fallback: legacy versions (classiObj.version)
    % ==========================================================
    app.Data.isRunsMode = false;

    try
        [dat, ~] = app.Data.classiObj.version;
        % Legacy cleanup: remove "# ROIs" column if present (expected columns: name,date,nRois,clsName,clsDate)
        try
            if size(dat,2) >= 5
                dat(:,3) = [];  % remove nRois (3rd col in legacy payload)
            end
        catch
        end


    catch MEv
        app.UITable.Data = {};
        uialert(app.TrainingrusselectionUIFigure, ...
            sprintf("No runs found in:\n%s\n\nAnd legacy version() failed:\n%s", runsDir, MEv.message), ...
            'No runs / legacy failed');
        return
    end

    if isempty(dat)
        app.UITable.Data = {};
        uialert(app.TrainingrusselectionUIFigure, ...
            sprintf("No runs found in:\n%s\n\nLegacy version() returned empty.", runsDir), ...
            'No runs found');
        return
    end

    st  = num2cell(false(size(dat,1),1));
    dat = [st, dat];

    app.UITable.Data = dat;
    app.UITable.ColumnName = {'Select'; 'Classification name'; 'Run Date'; '# ROIs'; 'Classifier Name'; 'Classifier Date'};
    app.UITable.ColumnWidth    = {50, 250, 150, 70, 250, 150};
    app.UITable.ColumnEditable = [true false false false false false];
    app.UITable.SelectionType  = 'row';

    try, app.UITable.Selection = []; catch, end
    app.localSetShowButtonsEnabled(~isempty(app.localGetSelectedRow()));

catch ME
    try
        uialert(app.TrainingrusselectionUIFigure, getReport(ME,'extended','hyperlinks','off'), 'BKGUI startup failed');
    catch
        disp(getReport(ME,'extended'));
    end
end


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
indices = event.Indices;
if isempty(indices), return; end
if indices(2) ~= 1, return; end % only first column

selected = find(cellfun(@(x) isequal(x,true), app.UITable.Data(:,1)));
di = setxor(selected, indices(1));

if ~isempty(di)
    app.UITable.Data(di,1) = {false};
end

% ---- enable/disable buttons based on checkbox selection ----
app.localSetShowButtonsEnabled(~isempty(app.localGetSelectedRow()));
            
        end

        % Button pushed function: RestoreselectedversioncloseButton
        function RestoreselectedversioncloseButtonPushed(app, event)
           
            idx = app.localGetSelectedRowForRestore();
if isempty(idx)
    uialert(app.TrainingrusselectionUIFigure,'Tick the checkbox of the run/version to restore.','Attention');
    return
end

d = uiprogressdlg(app.TrainingrusselectionUIFigure,'Title','Please Wait...', ...
    'Message','Restoring classifier...', 'Indeterminate','on');

try
    if app.Data.isRunsMode
        runDir = app.localGetRunDirFromRow(idx);
        classiRestoreFromRun(app.Data.classiObj, runDir);   % CLI function below
    else
        app.Data.classiObj.restore(idx); % legacy
    end


    close(d);
   % delete(app.UIFigure);

   % --- close BKGUI so caller can continue ---
app.Result.didRestore = true;
app.Result.mode       = string(app.Data.isRunsMode);
app.Result.runDir      = char(string(app.localGetRunDirFromRow(idx)));
app.Result.trainingParam = app.Data.classiObj.trainingParam;
app.Result.classes       = app.Data.classiObj.classes;
app.Result.channelName   = app.Data.classiObj.channelName;
app.Result.refreshed     = true;

try
    uiresume(app.TrainingrusselectionUIFigure);
catch
end

delete(app);   % ✅ actually close
return


catch ME
    try, close(d); catch, end
    uialert(app.TrainingrusselectionUIFigure, ME.message, 'Restore failed');
end

        end

        % Button pushed function: ShowtraininginfoButton
        function ShowtraininginfoButtonPushed(app, event)
            
idx = app.localGetSelectedRowForShow();
if isempty(idx)
    uialert(app.UIFigure,'Click a row first (highlight).','Attention');
    return
end

if ~app.Data.isRunsMode
    uialert(app.UIFigure,'Training info preview is only available in runs mode.','Info');
    return
end

runDir = app.localGetRunDirFromRow(idx);
if strlength(string(runDir))==0 || ~isfolder(runDir)
    uialert(app.UIFigure,'Run folder not found.','Error');
    return
end

wanted = { ...
    'CNN_accuracy.png', ...
    'CNN_loss.png', ...
    'LSTM_loss.png', ...
    'LSTM_accuracy.png' ...
};

paths = cell(size(wanted));
exists = false(size(wanted));
for k=1:numel(wanted)
    p = fullfile(runDir, wanted{k});
    paths{k} = p;
    exists(k) = exist(p,'file')==2;
end

if ~any(exists)
    uialert(app.UIFigure, ...
        sprintf('No training PNG found in run.\nExpected e.g. CNN_accuracy.png, CNN_loss.png, LSTM_loss.png\nFolder:\n%s', runDir), ...
        'Not found');
    return
end

% Create figure
fig = figure('Name', sprintf('Run training info: %s', app.Data.runNames{idx}), ...
    'NumberTitle','off');

% Determine layout based on how many exist
kShow = find(exists);
nShow = numel(kShow);

nCols = 2;
nRows = ceil(nShow / nCols);

for ii=1:nShow
    k = kShow(ii);
    ax = subplot(nRows, nCols, ii, 'Parent', fig);
    try
        I = imread(paths{k});
        imshow(I, 'Parent', ax);
        title(ax, wanted{k}, 'Interpreter','none');
    catch
        text(ax,0.1,0.5,sprintf('Failed to read %s', wanted{k}), 'Interpreter','none');
        axis(ax,'off');
    end
end

        end

        % Button pushed function: ShowstatsButton
        function ShowstatsButtonPushed(app, event)


idx = app.localGetSelectedRowForShow();
if isempty(idx)
    uialert(app.UIFigure,'Click a row first (highlight).','Attention');
    return
end

if ~app.Data.isRunsMode
    try
        sc = app.Data.classiObj.score;
        assignin('base','score', sc);
        openvar('score');
    catch
        uialert(app.UIFigure,'No score struct accessible in legacy mode.','Info');
    end
    return
end

runDir = app.localGetRunDirFromRow(idx);

cand = fullfile(runDir,'labelCounts.mat');
if exist(cand,'file')==2
    try
        S = load(cand);
        assignin('base','labelCounts', S);
        openvar('labelCounts');
        return
    catch ME
        uialert(app.UIFigure, ME.message, 'Load failed');
        return
    end
end

uialert(app.UIFigure, sprintf('No stats file found (e.g. labelCounts.mat) in:\n%s', runDir), 'Not found');


        end

        % Button pushed function: ShowparametersButton
        function ShowparametersButtonPushed(app, event)
         
idx = app.localGetSelectedRowForShow();
if isempty(idx)
    uialert(app.UIFigure,'Click a row first (highlight).','Attention');
    return
end

if ~app.Data.isRunsMode
    % legacy: show classif.trainingParam (best effort)
    try
        tp = app.Data.classiObj.trainingParam;
        assignin('base','trainingParam',tp);
        openvar('trainingParam');
    catch ME
        uialert(app.UIFigure,ME.message,'Cannot show parameters');
    end
    return
end

runDir = app.localGetRunDirFromRow(idx);
jsonFile = fullfile(runDir,'run.json');

if exist(jsonFile,'file')~=2
    uialert(app.UIFigure, sprintf('run.json not found in:\n%s', runDir), 'Not found');
    return
end

try
    raw = fileread(jsonFile);
catch ME
    uialert(app.UIFigure, ME.message, 'Read failed');
    return
end

% Pretty print best effort (jsondecode/jsonencode)
pretty = raw;
try
    S = jsondecode(raw);
    pretty = jsonencode(S);
    pretty = regexprep(pretty, ',"', sprintf(',\n"'));
    pretty = regexprep(pretty, '{', sprintf('{\n'));
    pretty = regexprep(pretty, '}', sprintf('\n}'));
catch
end

fig = uifigure('Name', sprintf('Run parameters (run.json): %s', app.Data.runNames{idx}), ...
    'Position', [200 200 900 600]);

ta = uitextarea(fig, 'Position', [10 10 880 580]);
ta.Editable = 'off';
ta.FontName = 'Consolas';
ta.Value = splitlines(string(pretty));


        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            

% Mouse highlight does not define the active classifier.
% Active = checkbox ticked in column 1.
app.localSetShowButtonsEnabled(~isempty(app.localGetSelectedRow()));
        end

        % Close request function: TrainingrusselectionUIFigure
        function TrainingrusselectionUIFigureCloseRequest(app, event)
             try
        uiresume(app.TrainingrusselectionUIFigure);
    catch
    end
    delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrainingrusselectionUIFigure = uifigure('Visible', 'off');
            app.TrainingrusselectionUIFigure.Name = 'Training rus selection';
            app.TrainingrusselectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @TrainingrusselectionUIFigureCloseRequest, true);
            app.RestoreselectedversioncloseButton = uibutton(app.TrainingrusselectionUIFigure, 'push');
            app.RestoreselectedversioncloseButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreselectedversioncloseButtonPushed, true);
            app.RestoreselectedversioncloseButton.Text = 'Restore selected version & close';
            app.UITable = uitable(app.TrainingrusselectionUIFigure);
            app.UITable.ColumnName = {'Select'; 'Classification name'; 'Run Date'; 'Classifier Name'; 'Classifier Date'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.ShowstatsButton = uibutton(app.TrainingrusselectionUIFigure, 'push');
            app.ShowstatsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowstatsButtonPushed, true);
            app.ShowstatsButton.Text = 'Show stats';
            app.ShowtraininginfoButton = uibutton(app.TrainingrusselectionUIFigure, 'push');
            app.ShowtraininginfoButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtraininginfoButtonPushed, true);
            app.ShowtraininginfoButton.Text = 'Show training info';
            app.ShowparametersButton = uibutton(app.TrainingrusselectionUIFigure, 'push');
            app.ShowparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowparametersButtonPushed, true);
            app.ShowparametersButton.Text = 'Show parameters';
            app.CurrentRunEditFieldLabel = uilabel(app.TrainingrusselectionUIFigure);
            app.CurrentRunEditFieldLabel.Text = 'Current Run :';
            app.CurrentRunEditField = uieditfield(app.TrainingrusselectionUIFigure, 'text');
            % Show the figure after all components are created
            app.TrainingrusselectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierBKGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrainingrusselectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrainingrusselectionUIFigure)
        end
    end
end