classdef classifierGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassifierUIFigure              matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveclassifierMenu              matlab.ui.container.Menu
        RestorepreviousclassifierMenu   matlab.ui.container.Menu
        ClassifierMenu                  matlab.ui.container.Menu
        FormattrainingsetMenu           matlab.ui.container.Menu
        TrainClassifierMenu             matlab.ui.container.Menu
        ValidateclassifierusingtestsetMenu  matlab.ui.container.Menu
        DisplaystatisticsMenu           matlab.ui.container.Menu
        LoadclassifierMenu              matlab.ui.container.Menu
        CheckstatusMenu                 matlab.ui.container.Menu
        SaveclassifierButton            matlab.ui.control.Button
        ClassifierisloadedinworksapcememoryLabel  matlab.ui.control.Label
        ParametersaresavedLabel         matlab.ui.control.Label
        StatusLoad                      matlab.ui.control.Lamp
        StatusSaved                     matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        ClassifierPropertiesTab         matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        OutputandstatisticsPanel        matlab.ui.container.Panel
        AnnotatedROIswithvalidationdataEditField  matlab.ui.control.EditField
        AnnotatedROIswithvalidationdataEditFieldLabel  matlab.ui.control.Label
        NumberofannotatedROIsEditField  matlab.ui.control.EditField
        NumberofannotatedROIsEditFieldLabel  matlab.ui.control.Label
        displayROIsusedforvalidationButton  matlab.ui.control.Button
        ValidateclassifierButton        matlab.ui.control.Button
        DisplayclassifierstatisticsButton  matlab.ui.control.Button
        TrainingvalidationsetPanel      matlab.ui.container.Panel
        DisplayaugmentedimagesButton    matlab.ui.control.Button
        TrainClassifierButton           matlab.ui.control.Button
        AvailableformattedtrainingsetLabel  matlab.ui.control.Label
        SettrainingparametersButton     matlab.ui.control.Button
        DisplaysampleimagesButton       matlab.ui.control.Button
        formatLabel                     matlab.ui.control.Label
        NumberROIS_2                    matlab.ui.control.EditField
        NumberofROIsusedfortrainingvalidationLabel_2  matlab.ui.control.Label
        ManageROIsformattrainingsetButton  matlab.ui.control.Button
        NumberROIS                      matlab.ui.control.EditField
        NumberofROIsusedfortrainingvalidationLabel  matlab.ui.control.Label
        ClassifierquickinfoPanel        matlab.ui.container.Panel
        UsercommentsEditField           matlab.ui.control.EditField
        UsercommentsEditFieldLabel      matlab.ui.control.Label
        SetclassfierparametersButton    matlab.ui.control.Button
        OpenfolderButton                matlab.ui.control.Button
        BackupButton                    matlab.ui.control.Button
        RestoreButton                   matlab.ui.control.Button
        backupversionsEditField         matlab.ui.control.EditField
        backupversionsEditFieldLabel    matlab.ui.control.Label
        ClassesEditField                matlab.ui.control.EditField
        ClassesEditFieldLabel           matlab.ui.control.Label
        TypeEditField                   matlab.ui.control.EditField
        TypeEditFieldLabel              matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        SetclassifierparametersTab      matlab.ui.container.Tab
        UsercommentsEditField_2         matlab.ui.control.EditField
        UsercommentsEditField_2Label    matlab.ui.control.Label
        DeSelectButton                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        ChannelListBoxSel               matlab.ui.control.ListBox
        SelectedchannelsasclassificationinputLabel  matlab.ui.control.Label
        ChannelListBox                  matlab.ui.control.ListBox
        AvailablechannelstakenfromtrainingdatasetROIsLabel  matlab.ui.control.Label
        PostprocessingparametersPanel   matlab.ui.container.Panel
        SizepixelEditField              matlab.ui.control.NumericEditField
        SizepixelEditFieldLabel         matlab.ui.control.Label
        FilteroutsmallobjectsCheckBox   matlab.ui.control.CheckBox
        ThresholdingButtonGroup         matlab.ui.container.ButtonGroup
        ValueEditField                  matlab.ui.control.EditField
        ValueEditFieldLabel             matlab.ui.control.Label
        MaximumprobabilityButton        matlab.ui.control.RadioButton
        AdaptivethresholdOtsumethodButton  matlab.ui.control.RadioButton
        FixedthresholdButton            matlab.ui.control.RadioButton
        WatershedCheckBox               matlab.ui.control.CheckBox
        PostprocessingDropDown          matlab.ui.control.DropDown
        PostprocessingDropDownLabel     matlab.ui.control.Label
        ClassiDetailsLabel              matlab.ui.control.Label
        PostprocessingcustomfunctionhandleEditField  matlab.ui.control.EditField
        PostprocessingcustomfunctionhandleEditFieldLabel  matlab.ui.control.Label
        SpaceseparatedclassnamesEditField  matlab.ui.control.EditField
        SpaceseparatedclassnamesLabel   matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        SettrainingparametersTab        matlab.ui.container.Tab
        SettrainingandvalidationsetROIsTab  matlab.ui.container.Tab
        BoundsnoticeLabel               matlab.ui.control.Label
        SetboundsselectionrulesButton   matlab.ui.control.Button
        FormattrainingsetfortrainingButton  matlab.ui.control.Button
        ShuffleROIsfractionEditField    matlab.ui.control.EditField
        ShuffleROIsfractionEditFieldLabel  matlab.ui.control.Label
        SelectROIsEditField             matlab.ui.control.EditField
        SelectROIsEditFieldLabel        matlab.ui.control.Label
        removeselectedROIButton         matlab.ui.control.Button
        DeselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        AnnotateselectedROIButton       matlab.ui.control.Button
        ImportROIsButton                matlab.ui.control.Button
        UITableData                     matlab.ui.control.Table
    end


    properties (Access = private)
        callingApp % Description
    isRefreshing logical = false
    end

    properties (Access = public)
        Data % Description % must be public to shar info with other apps
    end

    methods (Access = private)

        function refreshAll(app, varargin)
    % refreshAll(app) : refresh UI from app.Data.classiObj
    % refreshAll(app,'RebuildTrainingParam',true)

    p = inputParser;
    addParameter(p,'RebuildTrainingParam',true,@islogical);
    parse(p,varargin{:});
    rebuildTP = p.Results.RebuildTrainingParam;

    app.isRefreshing = true;
    c = app.Data.classiObj;

    % 1) Type dropdown cohérent (au cas où description change)
    if ~isempty(c.description) && ~isempty(c.description{1})
        descr = c.description{1};
        if iscell(descr), descr = descr{1}; end
        if ~any(matches(app.TypeDropDown.Items, descr))
            app.TypeDropDown.Items = [app.TypeDropDown.Items, {descr}];
        end
        app.TypeDropDown.Value = descr;
    end

    % 2) Classes -> s'assurer que roi.classes suit + colormap cohérente
    if isempty(c.colormap) && ~isempty(c.classes)
        c.colormap = shallowColormap(numel(c.classes));
    end

    if ~isempty(c.classes)
    c.classes = cellfun(@(s) strtrim(s), c.classes, 'UniformOutput', false);
end


    for i=1:numel(c.roi)
        c.roi(i).classes = c.classes;
    end
    app.Data.classiObj = c;

    % 3) Rebuild trainingParam GUI (struct2GUI)
    if rebuildTP
        % garantir existence + tip
        if isempty(c.trainingParam)
            c.trainClassifier('setparam');
        end
        if ~isfield(c.trainingParam,'tip')
            c.trainingParam.tip = {};
        end

        trainingParam = c.trainingParam;
        tip = trainingParam.tip;
        trainingParam = rmfield(trainingParam,'tip');

        app.SettrainingparametersTab.UserData = [];
        delete(app.SettrainingparametersTab.Children);

        while numel(tip) < numel(fieldnames(trainingParam))
            tip{end+1} = ' ';
        end

        struct2GUI(trainingParam,[10 510], ...
            'Handle',app.SettrainingparametersTab, ...
            'Flag',app.StatusSaved, ...
            'Tip',tip);

        app.SettrainingparametersTab.UserData.tip = tip;

        app.Data.classiObj = c;
    end

    % 4) Refresh panneaux
    displayClassi(app);
    displayProperties(app);
    displayData(app);

    % 5) Restore => considéré comme non-sauvé
    checkStatus(app,false);

    app.isRefreshing = false;
end


        function  displayClassi(app) % displays the data from classi as they are

            % classi type
            typelist=app.Data.classlist{:,2};
            classiObj=app.Data.classiObj;

            if numel(classiObj.description)==0 % new classi
                pix=1;
                classlist=app.Data.classlist;

                app.Data.classiObj.description={classlist{pix,2} ' - ' classlist{pix,3}};
                app.Data.classiObj.category=classlist{pix,4};
                app.Data.classiObj.classifyFun=classlist{pix,6}{1};
                app.Data.classiObj.trainingFun=classlist{pix,5}{1};
            end

            if numel(find(matches(app.TypeDropDown.Items,app.Data.classiObj.description{1})))
                app.TypeDropDown.Value=app.Data.classiObj.description{1};
            else
                app.TypeDropDown.Items=[app.TypeDropDown.Items app.Data.classiObj.description{1}];
                app.TypeDropDown.Value=app.Data.classiObj.description{1};
            end

            % classi description
            % if numel(classiObj.description)
            app.ClassiDetailsLabel.Text=classiObj.description{3};
            % end


            app.PostprocessingparametersPanel.Enable='off';
            app.PostprocessingDropDown.Enable='off';
            app.PostprocessingcustomfunctionhandleEditField.Enable='off';


            % channels

            app.ChannelListBoxSel.Items={};
            app.ChannelListBoxSel.Enable='off';

            cha={};

            
            switch classiObj.category{1}

                case 'Timeseries' % timeseries

                    if numel(classiObj.roi(1).results)
                    fi=fieldnames(classiObj.roi(1).results);
                    cc=1;
                     for i=1:numel(fi)
                         tmp=classiObj.roi(1).results.(fi{i});       
                         fi2=fieldnames(tmp); 

                         for j=1:numel(fi2)
                             
                             cha{cc}=['train.' fi{i} '.' fi2{j}];
                             
                             cc=cc+1;
                         end
                     end
                    end
                                   
                    
                  if numel(cha)
                        app.ChannelListBox.Items=cha;
                        app.ChannelListBox.Enable='on';
                    else
                        app.ChannelListBox.Items={};
                        app.ChannelListBox.Enable='off';
                  end
                  
                  if numel(classiObj.channelName)~=0 % in case previou system is used
                        channelName=classiObj.channelName;
                        app.ChannelListBoxSel.Items=channelName;
                        app.ChannelListBoxSel.Enable='on';
      
                  end


                otherwise % image classification 
                    
                    for i=1:numel(classiObj.roi)
                        if numel(classiObj.roi(i).id)
                            tmp=classiObj.roi(i).display.channel(:);
                            if size(tmp,1)>1
                                tmp=tmp';
                            end

                            cha=unique([cha tmp],'stable');

                        end
                    end

                    if numel(cha)
                        app.ChannelListBox.Items=cha;
                        app.ChannelListBox.Enable='on';
                    else
                        app.ChannelListBox.Items={};
                        app.ChannelListBox.Enable='off';
                    end

                    if numel(classiObj.channelName)==0 % in case previou system is used
                        if numel(cha) & numel(classiObj.channel)
                            if numel(cha)>= classiObj.channel(1) && classiObj.channel(1)>0
                                channelName=cha(classiObj.channel(1));
                                classiObj.channelName=channelName;
                                app.ChannelListBoxSel.Items=channelName;
                                app.ChannelListBoxSel.Enable='on';
                            end
                        else
                            channelName='';
                        end
                    else
                        channelName=classiObj.channelName;

                        if ischar(channelName) % fix previous classification format
                            channelName={channelName};
                            classiObj.channelName=channelName;
                        end

                        app.ChannelListBoxSel.Items=channelName;
                        app.ChannelListBoxSel.Enable='on';
                    end
            end

            % classes
            %   aaa=classiObj.description{1}
            if strcmp(classiObj.description{1},'Image Regression') || strcmp(classiObj.description{1},'LSTM Regression') % image regression : no classes needed
                classiObj.classes={};
                %   app.SpaceseparatedclassnamesEditField
                app.SpaceseparatedclassnamesEditField.Enable='off';

            else
                app.SpaceseparatedclassnamesEditField.Enable='on';
            end

            if numel(classiObj.classes)
                str='';
                for i=1:numel(classiObj.classes)
                    str=[str classiObj.classes{i} ' '];
                end
                app.SpaceseparatedclassnamesEditField.Value=str;
            else
                app.SpaceseparatedclassnamesEditField.Value='';
            end

            % postprocessing functions
            outputstr='';
            % outut functions


            if strcmp(classiObj.category{1},'Pixel')
                %   aa=classiObj.outputFun
                %   class(aa)
                app.PostprocessingDropDown.Enable='on';
                if numel(classiObj.outputType)
                    outputstr=classiObj.outputType;
                else
                    outputstr='proba';
                end
                %  outputstr

                switch outputstr
                    case 'proba'
                        outputstr='plain output / probabilities for each class';
                    case 'segmentation'
                        outputstr='plain output / semantic segmentation';
                    case 'postprocessing'
                        if ischar(classiObj.outputFun)
                            if strcmp(classiObj.outputFun,'post')
                                outputstr='postprocessing';
                            else
                                outputstr='custom function (see below)';
                            end
                        end
                end

                app.PostprocessingDropDown.Value=outputstr;


                if strcmp(outputstr,'custom function (see below)')
                    app.PostprocessingcustomfunctionhandleEditField.Enable='on';

                    if  numel(classiObj.outputFun)
                        if ischar(classiObj.outputFun)
                            app.PostprocessingcustomfunctionhandleEditField.Value=classiObj.outputFun;
                        end
                    end
                else
                    app.PostprocessingcustomfunctionhandleEditField.Enable='off';
                    app.PostprocessingcustomfunctionhandleEditField.Value='';
                end

                outputArg=classiObj.outputArg;
                app.WatershedCheckBox.Value=false;
                app.FilteroutsmallobjectsCheckBox.Value=false;
                app.SizepixelEditField.Value=10;
                app.FixedthresholdButton.Value=false;
                app.ValueEditField.Value=num2str(0.9);
                app.AdaptivethresholdOtsumethodButton.Value=false;
                app.MaximumprobabilityButton.Value=false;

                if  strcmp(outputstr,'postprocessing')

                    app.PostprocessingcustomfunctionhandleEditField.Value='post';
                    app.PostprocessingparametersPanel.Enable='on';
                    app.PostprocessingcustomfunctionhandleEditField.Value='post';

                    % display postprocessing parameters


                    for j=1:numel(outputArg)

                        if strcmp(outputArg{j},'watershed')
                            app.WatershedCheckBox.Value=true;
                        end

                        if strcmp(outputArg{j},'sizethreshold')
                            app.FilteroutsmallobjectsCheckBox.Value=true;
                            app.SizepixelEditField.Value=str2double(outputArg{j+1});
                        end
                        if strcmp(outputArg{j},'threshold')
                            app.FixedthresholdButton.Value=true;

                            app.ValueEditField.Value=num2str(outputArg{j+1});
                        end
                        if strcmp(outputArg{j},'adaptivethreshold')
                            app.AdaptivethresholdOtsumethodButton.Value=true;

                        end
                        if strcmp(outputArg{j},'maxproba')
                            app.MaximumprobabilityButton.Value=true;
                        end
                    end


                end
            else
                app.PostprocessingDropDown.Enable='off';
            end

            app.UsercommentsEditField_2.Value=classiObj.description{2};


        end

        function [boundsType, globalBounds] = localBoundsConfig(app,classiObj)
    boundsType = "Auto";
    globalBounds = [];

    if isprop(classiObj,'bounds') && isstruct(classiObj.bounds)
        if isfield(classiObj.bounds,'Type') && ~isempty(classiObj.bounds.Type)
            boundsType = string(classiObj.bounds.Type);
        end
        if isfield(classiObj.bounds,'Values')
            globalBounds = normalizeBounds(app,classiObj.bounds.Values);
        end
    end
end

function txt = localBoundsText(app,roiObj, pixdata, classiObj, boundsType, globalBounds)
    if strcmpi(boundsType,'Auto')
        if isempty(globalBounds)
            txt = 'all frames';
        else
            txt = sprintf('%d %d (global)', globalBounds(1), globalBounds(2));
        end
        return;
    end

    ud = [];
    if ~isempty(pixdata) && numel(roiObj.data) >= pixdata(1)
        if isfield(roiObj.data(pixdata(1)),'userData')
            ud = roiObj.data(pixdata(1)).userData;
        end
    end

    b = [];
    if strcmpi(boundsType,'Manual')
        b = readBoundsField(app,ud,'userbounds');
        if isempty(b), b = readBoundsField(app,ud,'bounds'); end
    elseif strcmpi(boundsType,'Rules')
        b = readBoundsField(app,ud,'bounds');
    else
        b = readBoundsField(app,ud,'bounds');
    end

    b = normalizeBounds(app,b);

    if isempty(b)
        txt = '[]';
    else
        txt = sprintf('%d %d', b(1), b(2));
    end
end

function txt = localBoundsNotice(app,boundsType, globalBounds)
    switch lower(string(boundsType))
        case "auto"
            if isempty(globalBounds)
                txt = 'Bounds: Auto (all frames)';
            else
                txt = sprintf('Bounds: Auto global [%d %d]', globalBounds(1), globalBounds(2));
            end
        case "manual"
            txt = 'Bounds: Manual (ROI by ROI)';
        case "rules"
            txt = 'Bounds: Rules';
        otherwise
            txt = sprintf('Bounds: %s', string(boundsType));
    end
end

function b = readBoundsField(app,ud, fieldName)
    b = [];
    if isstruct(ud) && isfield(ud, fieldName)
        b = ud.(fieldName);
    elseif isa(ud,'containers.Map') && isKey(ud, fieldName)
        b = ud(fieldName);
    end
end

function b = normalizeBounds(app,raw)
    b = [];

    if isempty(raw), return; end

    if iscell(raw), raw = raw{1}; end

    if ischar(raw) || isstring(raw)
        nums = regexp(char(string(raw)), '[-+]?\d*\.?\d+(?:[eE][-+]?\d+)?', 'match');
        if numel(nums) < 2, return; end
        raw = [str2double(nums{1}) str2double(nums{2})];
    end

    if ~isnumeric(raw), return; end
    raw = double(raw(:)');
    if numel(raw) < 2 || any(~isfinite(raw(1:2))), return; end

    b = round(raw(1:2));
    if b(1) == 0
        b = [];
        return;
    end
    if b(2) ~= 0 && b(2) < b(1)
        b = b([2 1]);
    end
end


        function displayData(app) % displays rois in the table 
            
             rois         = app.Data.classiObj.roi;
    trainingrois = app.Data.classiObj.trainingset;
    classiObj    = app.Data.classiObj;

   [boundsType, globalBounds] = localBoundsConfig(app,classiObj);


    % formatting roi table to be displayed :
    % columns :  selected for training , select for test, roi index, roi id ,
    % isannotated, isvalidated

    t    = app.UITableData;
    Data = {};

    if numel(rois) == 1
        if numel(rois.id) == 0
            Data   = {};
            t.Data = {};
            return;   % there is no roi available
        end
    end

    category = app.Data.classiObj.category{1};

    for i = 1:numel(rois)

        istrainee   = false;
        istest      = true;
        isannotated = false;
        isvalidated = false;

        if any(trainingrois == i)
            istrainee = true;
            istest    = false;
        end

        % =================================================================
        %                         PIXEL CLASSIFICATION
        % =================================================================
        if strcmp(category,'Pixel')

            t.ColumnName     = {'Select for training','Select for test',...
                                ' ROI index','ROI Id','Frame bounds'};
            t.ColumnWidth    = {150, 150, 100, 'auto', 150};
            t.ColumnEditable = [true true false false false];

            bounds = [];
            if numel(rois(i).data)
                listdata = {rois(i).data.groupid};
                pixdata  = find(matches(listdata, classiObj.strid));

                if ~isempty(pixdata)
                    if isfield(rois(i).data(pixdata).userData,'bounds')
                        bounds = rois(i).data(pixdata).userData.bounds;
                    else
                        bounds = [];
                    end
                end
            end

            boundsTxt = localBoundsText(app,rois(i), pixdata, classiObj, boundsType, globalBounds);
            Data(i,:) = {istrainee, istest, i, rois(i).id, boundsTxt};

        % =================================================================
        %                   IMAGE / LSTM / DELTA / AUTRES
        % =================================================================
        else

            % ---------- CHARGEMENT DES DATA ----------
            % On charge les data une fois pour chaque ROI (si besoin)
            if numel(rois(i).data) == 0
                rois(i).load('data');
            end

            % Raccourci si aucune data après tentative de load
            if numel(rois(i).data) == 0
                % rien sur disque → restera non annoté / non validé
                listdata = {};
                pixdata  = [];
            else
                listdata = {rois(i).data.groupid};
                pixdata  = find(matches(listdata, classiObj.strid));
            end

            % =================================================================
            %      CAS IMAGE / LSTM : tests sur labels_training / labels
            % =================================================================
            if ismember(category, {'Image','LSTM'})

                t.ColumnName = {'Select for training','Select for testing',...
                                ' ROI index','ROI Id','is annotated',...
                                'is validated','Frame bounds'};
                t.ColumnWidth    = {150, 150, 100, 'auto', 100, 100, 100};
                t.ColumnEditable = [true true false false false false false];

                labelsTraining = [];
                labelsPred     = [];

                if ~isempty(pixdata)
                    dd = rois(i).data(pixdata);

                    % --- labels_training : annotation manuelle ---
                    try
                        labelsTraining = dd.getData('labels_training');
                    catch
                        labelsTraining = [];
                    end

                    % --- labels : prédiction / validation ---
                    try
                        labelsPred = dd.getData('labels');
                    catch
                        labelsPred = [];
                    end
                end

                % --------- isannotated : basé sur labels_training ----------
                % Annoté si au moins une étiquette != 'uncategorized'
                if ~isempty(labelsTraining)
                    labStr = string(labelsTraining);
                    labStr = labStr(:);
                    % on ignore les valeurs manquantes
                    labStr = labStr(~ismissing(labStr));
                    if ~isempty(labStr)
                        isannotated = any(labStr ~= "uncategorized");
                    end
                end

                % --------- isvalidated : basé sur labels  -------------------
                % Validé si au moins une étiquette prédite != 'uncategorized'
                if ~isempty(labelsPred)
                    labStr = string(labelsPred);
                    labStr = labStr(:);
                    labStr = labStr(~ismissing(labStr));
                    if ~isempty(labStr)
                        isvalidated = any(labStr ~= "uncategorized");
                    end
                end

                % --------- bounds éventuels -------------------------------
boundsTxt = localBoundsText(app, rois(i), pixdata, classiObj, boundsType, globalBounds);
Data(i,:) = {istrainee, istest, i, rois(i).id, ...
             isannotated, isvalidated, boundsTxt};


            % =================================================================
            %      AUTRES CATÉGORIES (ex. DELTA) : on garde ta logique prob_
            % =================================================================
            else
                % Ancien comportement pour Delta / autres
                t.ColumnName = {'Select for training','Select for testing',...
                                ' ROI index','ROI Id','is annotated',...
                                'is validated','Frame bounds'};
                t.ColumnWidth    = {150, 150, 100, 'auto', 100, 100, 100};
                t.ColumnEditable = [true true false false false false false];

                % ----- isannotated (ancien test via id_training) ---------
                if ~isempty(pixdata)
                    dd = rois(i).data(pixdata);
                    try
                        ddts = dd.getData('id_training');
                        if sum(ddts) > 0
                            isannotated = true;
                        end
                    catch
                        % laisse false si id_training indisponible
                    end
                end

                % ----- isvalidated (ancien test via prob_* ) --------------
                if ~isempty(pixdata)
                    dd      = rois(i).data(pixdata);
                    probtmp = 0;
                    for kclass = 1:numel(classiObj.classes)
                        fieldName = ['prob_ ' classiObj.classes{kclass}];
                        try
                            ddts    = dd.getData(fieldName);
                            probtmp = probtmp + sum(ddts(:));
                        catch
                        end
                    end
                    if probtmp > 0
                        isvalidated = true;
                    end
                end

                % ----- bounds éventuels -----------------------------------
                boundsTxt = localBoundsText(app, rois(i), pixdata, classiObj, boundsType, globalBounds);
Data(i,:) = {istrainee, istest, i, rois(i).id, ...
             isannotated, isvalidated, boundsTxt};

            end

        end
    end

    t.Data = Data;
   app.BoundsnoticeLabel.Text = localBoundsNotice(app,boundsType, globalBounds);

        end

        function  checkStatus(app,saving)
            % checks whether classi parameters are saved and classifier is
            % in memory

            classiObj=app.Data.classiObj;

            [~, check]=classiObj.loadClassifier('check');

            if check
                app.StatusLoad.Color=[0 1 0];

            end
            %   end

            % checks whether paramas have changed
            if nargin==2

                if saving==false
                    app.StatusSaved.Color=[1 0 0];
                else
                    app.StatusSaved.Color=[0 1 0];
                end
            end
        end
        
        function displayProperties(app)

            % display classi generic parameters

            classiObj=app.Data.classiObj;
            app.PathEditField.Value=classiObj.getPath;
            
            st1=classiObj.description{1};

            if iscell(st1)
                st1=st1{1};
            end

              st3=classiObj.description{3};

            if iscell(st3)
                st3=st3{1};
            end

            app.TypeEditField.Value= [st1 ' // ' st3];

            str='';
                 for i=1:numel(classiObj.classes)
                    str=[str classiObj.classes{i} ' '];
                 end
              
                app.ClassesEditField.Value=str;

            % display user comments 
            app.UsercommentsEditField.Value=classiObj.description{2};


         [t,in] =classiObj.version;
        app.backupversionsEditField.Value=num2str(size(t,1));

        % display classi ROIs and training set info 
        if numel(classiObj.roi(1).id)
        app.NumberROIS.Value=num2str(numel(classiObj.roi));
        n=numel(classiObj.roi);
        else
        app.NumberROIS.Value='0';
        n=0;
        end

        if n>0

        app.NumberROIS_2.Value= num2str( double(length(classiObj.trainingset))./double(n));
        else
        app.NumberROIS_2.Value='0';
        end

       pth=classiObj.getPath;
       
       % collect the number of images
     [ k, ~]=classiObj.displayFormattedTrainingSet;

     if numel(k)==0
         app.formatLabel.Text='This classfier has no formatted data';
     else
         tmpstr='';
         cc=0;
         for i=1:size(k,1)
                tmpstr=[tmpstr 'Folder: "' k{i,1} '" : ' num2str(k{i,2}) ' images'  newline];
                cc=cc+k{i,2};
         end
      tmpstr=[tmpstr 'Total number of images: ' num2str(cc)];

         app.formatLabel.Text=tmpstr;
     end


     % display statistics
     % number of annotated ROIs. 

     cn=0;
     cm=0;
     cate=classiObj.category{1};
     switch cate
    case {'Image','LSTM'}
     for i=1:numel(classiObj.roi)
        if isfield(classiObj.roi(i).train,classiObj.strid)
            if isfield(classiObj.roi(i).train.(classiObj.strid),'id')
                if numel(classiObj.roi(i).train.(classiObj.strid).id)>0
                    if numel(find(classiObj.roi(i).train.(classiObj.strid).id>0))
                     cn=cn+1;
                    end
                end
            end
           
        end
         if isfield(classiObj.roi(i).results,classiObj.strid)
            if isfield(classiObj.roi(i).results.(classiObj.strid),'id')
                if numel(classiObj.roi(i).train.(classiObj.strid).id)>0
                 %   if numel(find(classiObj.roi(i).train.(classiObj.strid).id>0))
                     cm=cm+1;
                  %  end
                end
            end
           
        end
     end
     app.NumberofannotatedROIsEditField.Value=num2str(cn);
     app.AnnotatedROIswithvalidationdataEditField.Value=num2str(cm);

         case {'Pixel'}
    for i=1:numel(classiObj.roi)
    %    pix=classiObj.roi(i).findChannelID(classiObj.strid);
     %   if numel(pix)
      %      im=classiObj.
    %end
    end
 app.AnnotatedROIswithvalidationdataEditField.Value='Not available' ;% need to load every single ROI, which is too time consuming
app.NumberofannotatedROIsEditField.Value='Not available'; % need to load every single ROI, which is too time consuming
 
        end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classiObj)
            % HERE
            % app.callingApp=callingApp;
            % displayNodes(callingApp)
            %  gatherVarsFromWorkspace(callingApp)

            if nargin==1
                classiObj=classi;
                classiObj.path='';
                classiObj.strid='No input classi was provided';
            end

            app.ClassifierUIFigure.Name=[classiObj.strid];
            app.Data.classiObj=classiObj;

            str=which('shallowNew.m');
            [pth file ext]=fileparts(str);
            str=[pth '/classification/classlist.mat'];
            load(str);
            app.Data.classlist=classlist;
            app.TypeDropDown.Items=classlist{:,2};
            app.Data.selected=[];

            if nargin>1
                displayClassi(app);
            end

            if numel(classiObj.trainingParam)==0
                classiObj.trainClassifier('setparam');
            end
            
            if ~isfield(classiObj.trainingParam,'transfer_learning')
                [t,lastIndex]=classiObj.version;
                if numel(t{1,1})==0
                str={};
                else
                str=t(:,4);
                end
                str=['ImageNet', str', 'ImageNet'];
                classiObj.trainingParam.transfer_learning=str;
                classiObj.trainingParam.tip{end+1}='Select version of the classifier to be used';
                checkStatus(app,false);
            else
                [t,lastIndex]=classiObj.version;
                   if numel(t{1,1})==0
                str={};
                else
                str=t(:,4);
                   end
                str=['ImageNet', str', classiObj.trainingParam.transfer_learning{end}];
                classiObj.trainingParam.transfer_learning=str;
                checkStatus(app,true);
            end

            trainingParam=classiObj.trainingParam;
            tip=trainingParam.tip;
            trainingParam=rmfield(trainingParam,'tip');
            app.SettrainingparametersTab.UserData=[];
            delete(app.SettrainingparametersTab.Children);
            
            while numel(tip)<numel(fieldnames(trainingParam))
                  tip{end+1}=' ';
            end

            struct2GUI(trainingParam,[10 510],'Handle',app.SettrainingparametersTab,'Flag',app.StatusSaved,'Tip',tip);
            app.SettrainingparametersTab.UserData.tip=tip;

            displayProperties(app)
        end

        % Close request function: ClassifierUIFigure
        function ClassifierUIFigureCloseRequest(app, event)
             % ---- 1) Vérifier si les paramètres/classifier sont sauvegardés ----
    % Remplace ceci par ton vrai flag de sauvegarde

    isSaved = isequal(app.StatusSaved.Color,[0 1 0]);
    % Ou : isSaved = strcmp(app.ParametersSavedLamp.Color,'green');


     if ~isSaved
        % ---- 2) Show confirmation dialog in English ----
        selection = uiconfirm(app.ClassifierUIFigure, ...
            ['The classifier has not been saved.', newline, ...
             'Are you sure you want to close the window?'], ...
             'Unsaved Changes', ...
             'Options', {'Close without saving','Cancel'}, ...
             'DefaultOption', 2, 'Icon','warning');

        % ---- 3) Cancel if the user does not confirm ----
        if strcmp(selection, 'Cancel')
            return
        end
    end


            delete(app)
        end

        % Callback function
        function SaveclassifierparametersButtonPushed(app, event)
            disp('Saving classifiers')

            d = uiprogressdlg(app.ClassifierUIFigure,'Title','Please Wait...',...
                'Message','Saving current classifier and trainingset...');
            d.Value=0.33;


            if numel(app.UITableData.Data)
                selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
                app.Data.classiObj.trainingParam.rois=find(selectedfortraining');
            else
                app.Data.classiObj.trainingParam.rois =1:numel(app.Data.classiObj.roi);
            end

            classiSave(app.Data.classiObj);

            d.Value=0.67;
            d.Message='Saving training parameters...';


            d.Value=1;
            close(d)

        end

        % Button down function: SettrainingparametersTab
        function SettrainingparametersTabButtonDown(app, event)
    % 
    % 
    %         % --- 1) Récupérer le classifier ---
    % if ~isfield(app.Data, 'classiObj') || isempty(app.Data.classiObj)
    %     % adapte le nom de la figure si nécessaire (UIFigure / ClassifierUIFigure)
    %     uialert(app.UIFigure, ...
    %         'No classifier object found in app.Data.classiObj.', ...
    %         'Error', 'Icon', 'error');
    %     return;
    % end
    % 
    % classif = app.Data.classiObj;  % handle class, modifiée par effet de bord
    % 
    % % --- 2) Vérifier si les paramètres de training sont déjà définis ---
    % hasParam = ~isempty(classif.trainingParam)
    % 
    % if ~hasParam
    %     % --- 3) Initialiser via la fonction de training en mode "init" ---
    %     if isempty(classif.trainingFun)
    %         uialert(app.UIFigure, ...
    %             'No training function defined for this classifier (trainingFun is empty).', ...
    %             'Error', 'Icon', 'error');
    %         return;
    %     end
    % 
    %     try
    %         funHandle = str2func(classif.trainingFun);
    % 
    %         % Convention : trainXXXFun(classif, <quelquechose>) avec nargin==2
    %         % déclenche le mode "setparam". La valeur du 2e argument n'a pas d'importance.
    %         funHandle(classif, 'init');
    % 
    %         % classif est un handle → app.Data.classiObj est déjà à jour,
    %         % mais on peut réassigner pour être explicite :
    %         app.Data.classiObj = classif;
    % 
    %     catch ME
    %         uialert(app.UIFigure, ...
    %             sprintf('Failed to initialize training parameters using %s:\n\n%s', ...
    %                     classif.trainingFun, ME.message), ...
    %             'Error', 'Icon', 'error');
    %         return;
    %     end
    % end
    % 
    % % --- 4) Ici tu peux rafraîchir l’affichage des champs de trainingParam ---
    % % Par ex. si tu as une méthode dédiée :
    % % updateTrainingParamUI(app);


        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            
            if app.isRefreshing, return; end

            value = app.TypeDropDown.Value;
            pix=find(matches(app.TypeDropDown.Items,value));

            test=uiconfirm(app.ClassifierUIFigure,'Training parameters will be erased if you change the type of classifer ! Proceed?','Warning','Icon','Warning');

            if strcmp(test,'Cancel')
                return;
            end


            app.Data.classiObj.typeid=pix;

            classlist=app.Data.classlist;

            app.Data.classiObj.description={classlist{pix,2} ' - ' classlist{pix,3}};
            app.Data.classiObj.category=classlist{pix,4};
            app.Data.classiObj.classifyFun=classlist{pix,6}{1};
            app.Data.classiObj.trainingFun=classlist{pix,5}{1};

            app.Data.classiObj.trainingParam=[];
            
            % reset parameters
            app.Data.classiObj.trainClassifier('setparam');
            classiObj=app.Data.classiObj;
            
            % set parameter menu:
              if ~isfield(classiObj.trainingParam,'transfer_learning')
                [t,lastIndex]=classiObj.version;
                 if numel(t{1,1})==0
                str={};
                else
                str=t(:,4);
                 end
                str=['ImageNet', str', 'ImageNet'];
                classiObj.trainingParam.transfer_learning=str;
                classiObj.trainingParam.tip{end+1}='Select version of the classifier to be used';
                checkStatus(app,false);
            else
                [t,lastIndex]=classiObj.version;
                 if numel(t{1,1})==0
                str={};
                else
                str=t(:,4);
                 end
                str=['ImageNet', str', classiObj.trainingParam.transfer_learning{end}];
                classiObj.trainingParam.transfer_learning=str;
                checkStatus(app,true);
              end
            
            trainingParam=classiObj.trainingParam;
            tip=trainingParam.tip;
            trainingParam=rmfield(trainingParam,'tip');
            app.SettrainingparametersTab.UserData=[];
            delete(app.SettrainingparametersTab.Children);
            struct2GUI(trainingParam,[10 510],'Handle',app.SettrainingparametersTab,'Flag',app.StatusSaved,'Tip',tip);
            app.SettrainingparametersTab.UserData.tip=tip;
            
            trainingParam=app.Data.classiObj.trainingParam;
            tip=trainingParam.tip;
            trainingParam=rmfield(trainingParam,'tip');
            app.SettrainingparametersTab.UserData=[];
            delete(app.SettrainingparametersTab.Children);
            struct2GUI(trainingParam,[10 510],'Handle',app.SettrainingparametersTab,'Flag',app.StatusSaved,'Tip',tip);
            app.SettrainingparametersTab.UserData.tip=tip;
            
            % aa=classlist{pix,3}
            displayClassi(app);
            displayProperties(app);
            
        end

        % Callback function
        function ChannelnameusedasinputEditFieldValueChanged(app, event)
            value = app.ChannelnameusedasinputEditField.Value;
            app.Data.classiObj.channel=str2num(value);
            checkStatus(app,false)
        end

        % Value changed function: SpaceseparatedclassnamesEditField
        function SpaceseparatedclassnamesEditFieldValueChanged(app, event)

    if app.isRefreshing, return; end

    % ---- Parse new classes from edit field ----
    value = string(app.SpaceseparatedclassnamesEditField.Value);
    newClasses = strtrim(split(value));
    newClasses(newClasses=="") = [];
    newClasses = cellstr(newClasses(:));

    % ---- Old classes (normalize to column cellstr) ----
    oldClasses = app.Data.classiObj.classes;
    if isstring(oldClasses), oldClasses = cellstr(oldClasses); end
    oldClasses = oldClasses(:);

    % ---- No change -> nothing to do ----
    if isequal(oldClasses, newClasses)
        return;
    end

    % ---- Ask mapping (only once) if we had old classes ----
    mapping = [];  % leave empty if no old classes
    if ~isempty(oldClasses)
        [oldToNew, cancelled] = classMappingDialog(app.ClassifierUIFigure, oldClasses, newClasses);
        if cancelled
            % revert edit field to keep state consistent
            app.isRefreshing = true;
            app.SpaceseparatedclassnamesEditField.Value = strjoin(oldClasses, " ");
            app.isRefreshing = false;
            return;
        end
        mapping = oldToNew; % containers.Map old -> new (or "" for delete)
    end

    % ---- Apply via class method (this also updates ROI classes + LSTM training ds) ----
    try
        app.Data.classiObj.setClasses(newClasses, ...
            'mapping', mapping, ...
            'storeMapping', ~isempty(mapping), ...
            'updateROIData', true);  % you can set false if you want to delay ROI ds migration
    catch ME
        % If something fails, revert edit field and rethrow (or alert)
        app.isRefreshing = true;
        app.SpaceseparatedclassnamesEditField.Value = strjoin(oldClasses, " ");
        app.isRefreshing = false;
        rethrow(ME);
    end

    % ---- Refresh UI once ----
    displayProperties(app);
    checkStatus(app,false);

        end

        % Callback function
        function PostprocessingfunctionhandleEditFieldValueChanged(app, event)
            value =PostprocessingfunctionhandleEditFieldValueChanged.Value;
            app.Data.classiObj.outputFun=str2func(value);
            checkStatus(app,false)
        end

        % Value changed function: PostprocessingDropDown
        function PostprocessingDropDownValueChanged(app, event)
            if app.isRefreshing, return; end

            value = app.PostprocessingDropDown.Value;
            pix=find(contains(app.PostprocessingDropDown.Items,value));

            switch pix
                case 1
                    app.Data.classiObj.outputType='proba';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                case 2
                    app.Data.classiObj.outputType='segmentation';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                case 3
                    app.Data.classiObj.outputType='postprocessing';
                    app.Data.classiObj.outputFun='post';
                    app.Data.classiObj.outputArg={'threshold','0.9'};
                case 4
                    app.Data.classiObj.outputType='postprocessing';
                    app.Data.classiObj.outputFun='';
                    app.Data.classiObj.outputArg={};
                    app.PostprocessingcustomfunctionhandleEditField.Value='';
            end

            checkStatus(app,false)
            displayClassi(app);
        end

        % Value changed function: 
        % PostprocessingcustomfunctionhandleEditField
        function PostprocessingcustomfunctionhandleEditFieldValueChanged2(app, event)
            value = app.PostprocessingcustomfunctionhandleEditField.Value;
            app.Data.classiObj.outputFun=value;
            checkStatus(app,false)
        end

        % Button down function: SettrainingandvalidationsetROIsTab
        function SettrainingandvalidationsetROIsTabButtonDown(app, event)
            displayData(app);
        end

        % Cell edit callback: UITableData
        function UITableDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2)==2
                app.UITableData.Data{indices(1),1}=~app.UITableData.Data{indices(1),1};
            end
            if indices(2)==1
                app.UITableData.Data{indices(1),2}=~app.UITableData.Data{indices(1),2};
            end

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');


            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: AnnotateselectedROIButton
        function AnnotateselectedROIButtonPushed(app, event)
            sel=app.Data.selected;
            if numel(sel)==0
                uialert(app.ClassifierUIFigure,'First select an ROI!','Error')
                return;
            end
            disp(['Launching ROI #' num2str(sel) '.... Please wait...']);
            app.Data.classiObj.userTraining('Roi',sel);
            checkStatus(app,false)
        end

        % Cell selection callback: UITableData
        function UITableDataCellSelection(app, event)
            indices = event.Indices;
            if numel(indices)
            app.Data.selected=indices(1);
            end

        end

        % Button pushed function: ImportROIsButton
        function ImportROIsButtonPushed(app, event)

            roiImporterGUI(app)
            uiwait(app.ClassifierUIFigure);

            displayData(app);
            displayClassi(app); % uodates the channel in particular in the classi tab
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            
            n=size(app.UITableData.Data,1);
            t=cell(1,n);
            t(:)={true};
  
            app.UITableData.Data(:,1)=t';
            
            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            n=size(app.UITableData.Data,1);
            t=cell(1,n);
            t(:)={false};
  
            app.UITableData.Data(:,1)=t';

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
        end

        % Button pushed function: removeselectedROIButton
        function removeselectedROIButtonPushed(app, event)


    % Récupérer toutes les lignes sélectionnées dans le UITable
    indices = app.UITableData.Selection;   % [n x 2] (row, col)
    if isempty(indices)
        return
    end
    sel = unique(indices(:,1));           % lignes sélectionnées (peut être plusieurs)

    nRoi = numel(app.Data.classiObj.roi);

    % Nettoyage des indices hors bornes
    sel = sel(sel >= 1 & sel <= nRoi);
    if isempty(sel)
        return
    end

    % ROIs à conserver = toutes sauf celles sélectionnées
    keep = setdiff(1:nRoi, sel, 'stable');

    if isempty(keep)
        % On a tout supprimé
        app.Data.classiObj.roi = roi;
        app.Data.classiObj.channelName = {};
        app.UITableData.Selection = [];
        displayClassi(app);
    else
        % On garde seulement les ROIs non sélectionnées
        app.Data.classiObj.roi = app.Data.classiObj.roi(keep);
        app.UITableData.Selection = [];
        
        % Cas particulier : il ne reste qu’une ROI “placeholder”
        if numel(app.Data.classiObj.roi) == 1 && ...
                isempty(app.Data.classiObj.roi(1).id)
            app.Data.classiObj.channelName = {};
            displayClassi(app);
        end
    end

    % Mise à jour de l’état et de l’affichage
    checkStatus(app,false);
    displayData(app);

        end

        % Menu selected function: DisplaystatisticsMenu
        function DisplaystatisticsMenuSelected(app, event)
            classiObj=app.Data.classiObj;
            aa=app.UITableData.Data;

            if numel(aa)==0
                uialert(app.ClassifierUIFigure,'First select the testset in the dataset tab or make sure you have ROIs!','Error');
                return;
            end


            % gather the test set.

            selectedfortest=find(cellfun(@(x) x==0,app.UITableData.Data(:,1)));
            classiObj.stats('Confusion','Classes','Rois',selectedfortest','Force');
        end

        % Menu selected function: ValidateclassifierusingtestsetMenu
        function ValidateclassifierusingtestsetMenuSelected(app, event)

            classiObj=app.Data.classiObj;


            classifyDataGUI(classiObj,"Validation");
            return;
%             
% 
% %             if ~exist(fullfile(classiObj.path,'trainingParam.mat'))
% %                 uialert(app.ClassifierUIFigure,'Error','Training parameters were not saved !')
% %                 return;
% %             end
% 
%             d = uiprogressdlg(app.ClassifierUIFigure,'Title','Please Wait...',...
%                 'Message','Checking number of frames');
%             d.Value=0.1;
% 
%             if numel(classiObj.roi(1).image)==0
%                 classiObj.roi(1).load;
%             end
% 
%             nframes=size(classiObj.roi(1).image,4);
% 
%             prompt = {'Process only ROIs/Frames in which groundtruth is available (yes -> 1; no -> 0):','Frames to be processed: (write -1 if variable frame numbers)','Use additional CNN only classification (yes -> 1; no -> 0) - for LSTM classification'};
%             dlgtitle = 'Input classifier validation parameters';
% 
%             dims = [1 100];
% 
%             definput = {'1',['1:' num2str(nframes)],'0'};%, num2str(inte)};
%             answer = inputdlg(prompt,dlgtitle,dims,definput);
% 
%             if numel(answer)==0
%                 return;
%             end
% 
%             % here bug suspected
%             if numel(app.UITableData.Data)==0
%                 displayData(app);
%             end
% 
%             selectedfortesting=cellfun(@(x) x==0,app.UITableData.Data(:,1));
% 
%             roiobj=classiObj.roi(selectedfortesting);
% 
%             d = uiprogressdlg(app.ClassifierUIFigure,'Title','Please Wait...',...
%                 'Message','Checking network classifier performance using groundtruth data // test dataset; Please wait...');
%             d.Value=0.2;
% 
%             arg={};
%             cc=1;
%             if strcmp(answer{1},'1')
%                 arg{cc}='RoiWithGT';
%                 cc=cc+1;
%             end
% 
% %             if strcmp(answer{3},'1')
% %                 arg{cc}='Parallel';
% %                 cc=cc+1;
% %             end
% 
%             arg{cc}='Frames';   
%             cc=cc+1;
%             arg{cc}=answer{2};
%             cc=cc+1;
% 
%             if strcmp(answer{3},'1')
%                 arg{cc}='ClassifierCNN';
%                 cc=cc+1;
%             end
% 
%             arg{cc}='Progress';
%             cc=cc+1;
%             arg{cc}=d;
%             cc=cc+1;
% 
%             classiObj.validateTrainingData(roiobj,arg{:});
% 
%             d.Value=1;
% 
%             pause(1);
%             close(d)
%                uialert(app.ClassifierUIFigure,'Validation is complete!','Success','Icon','success');
        end

        % Menu selected function: FormattrainingsetMenu
        function FormattrainingsetMenuSelected(app, event)
  classiObj = app.Data.classiObj;
    nrois = classiObj.trainingset; % gv

    if numel(nrois) == 0
        uialert(app.ClassifierUIFigure, ...
            'You must select at least one ROI in the Dataset panel  !', ...
            'Error');
        return;
    end

    % === Prompts ===
    prompt = { ...
        'Frames to be processed (0 = all)'};
    dlgtitle = 'Input formatting parameters';
    dims = [1 100];
    definput = {'0'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    % === Parsing & validation ===
    framesToProcess = str2double(answer{1});
    if isnan(framesToProcess) || framesToProcess < 0
        uialert(app.ClassifierUIFigure,'Frames must be 0 or a positive number.','Error');
        return;
    end

    % === Progress dialog ===
    d = uiprogressdlg(app.ClassifierUIFigure, ...
        'Title','Please Wait...', ...
        'Message','Exporting trainingset to files; Please wait...');
    d.Value = 0.33;

    % === Construire les Name-Value pairs dynamiquement ===
    args = {'Frames', framesToProcess};

   
    % === Appel ===
    output = app.Data.classiObj.formatDataForTraining(args{:});

    d.Value = 0.66;

    if numel(output)>0
        d.Message = [num2str(output) ' files/images were exported; The classifier is ready to be trained!'];
        strr = d.Message;
    else
        d.Message = 'No file was exported; Check your trainingset!';
        strr = d.Message;
        pause(1)
    end

    close(d)
    displayProperties(app);
    uialert(app.ClassifierUIFigure,strr,'Success','Icon','success');
        end

        % Menu selected function: TrainClassifierMenu
        function TrainClassifierMenuSelected(app, event)
            classiObj=app.Data.classiObj;

            if app.StatusSaved.Color==[1 0 0]
                uialert(app.ClassifierUIFigure,'Error','Training parameters were not saved !')
               
                 return;
            end

            nrois=classiObj.trainingset; 
            if numel(nrois)==0
                  uialert(app.ClassifierUIFigure,'You must select at least one ROI in the Dataset panel  !','Error')
                 return;
            end

            d = uiprogressdlg(app.ClassifierUIFigure,'Title','Please Wait...',...
                'Message','Training network; Please wait...');
            d.Value=0.5;

            [~, check]=classiObj.loadClassifier('check');
            if check==1
                evalin('base',['clear ' classiObj.strid]);
            end

            checkStatus(app);


            classiObj.trainClassifier;

            d.Value=1;

            pause(1);
            close(d)

           uialert(app.ClassifierUIFigure,'Training is complete!','Success','Icon','success');
        end

        % Menu selected function: SaveclassifierMenu
        function SaveclassifierMenuSelected(app, event)
            disp('Saving classifiers')

            d = uiprogressdlg(app.ClassifierUIFigure,'Title','Please Wait...',...
                'Message','Saving current classifier and trainingset...');
            d.Value=0.33;

            if ~isempty(app.UITableData.Data)
    selectedfortraining = cellfun(@(x) x==1, app.UITableData.Data(:,1));
    app.Data.classiObj.trainingset = find(selectedfortraining');
elseif isempty(app.Data.classiObj.trainingset)
    % fallback une seule fois dans la vie du classif, au tout début
    app.Data.classiObj.trainingset = 1:numel(app.Data.classiObj.roi);
end


            app.Data.classiObj.trainingParam=app.SettrainingparametersTab.UserData;

            classiSave(app.Data.classiObj);

            d.Value=0.67;
            d.Message='Saving training parameters...';

            %a=app.Data.classiObj.path

            pause(0.1);
            close(d)

            checkStatus(app,true)
        end

        % Callback function
        function ChannelsavailableintrainingsetDropDownValueChanged(app, event)
            value = app.ChannelsavailableintrainingsetDropDown.Value;

            app.ChannelnameusedasinputEditField.Value=value;
            app.Data.classiObj.channelName=value;
            app.Data.classiObj.channel(1)= find(matches(app.ChannelsavailableintrainingsetDropDown.Items,value));
            checkStatus(app,false)
        end

        % Callback function
        function ChannelsavailableintrainingsetDropDown_2ValueChanged(app, event)
            value = app.ChannelsavailableintrainingsetDropDown_2.Value;
            app.AdditionalchannelforclassificationobjectsorregressionEditField.Value=value;
            app.Data.classiObj.channelName2=value;
            app.Data.classiObj.channel(2)= find(matches(app.ChannelsavailableintrainingsetDropDown_2.Items,value));
            checkStatus(app,false)
        end

        % Value changed function: WatershedCheckBox
        function WatershedCheckBoxValueChanged(app, event)
            value = app.WatershedCheckBox.Value;
            str= app.Data.classiObj.outputArg;

            if value==false
                str=setxor(str,'watershed','stable') ;
            else
                str=[str 'watershed'];
            end


            app.Data.classiObj.outputArg=str;
            checkStatus(app,false);
            displayClassi(app);
        end

        % Value changed function: FilteroutsmallobjectsCheckBox
        function FilteroutsmallobjectsCheckBoxValueChanged(app, event)
            value = app.FilteroutsmallobjectsCheckBox.Value;
            str= app.Data.classiObj.outputArg;

            if value==false
                pix=find(matches(str,'sizethreshold'));
                if numel(pix)
                    val=str(pix+1);
                    str=setxor(str,val{1},'stable');
                end
                str=setxor(str,'sizethreshold','stable');
                %       str
            else
                str=[str 'sizethreshold' num2str(app.SizepixelEditField.Value)];
            end

            app.Data.classiObj.outputArg=str;
            checkStatus(app,false);
            displayClassi(app);
        end

        % Value changed function: SizepixelEditField
        function SizepixelEditFieldValueChanged(app, event)
            value = app.SizepixelEditField.Value;
            str= app.Data.classiObj.outputArg;

            pix=find(matches(str,'sizethreshold'));
            if numel(pix)
                val=str(pix+1);
                str{pix+1}=num2str(value);
                app.Data.classiObj.outputArg=str;
                checkStatus(app,false);
                displayClassi(app);
            end

        end

        % Selection changed function: ThresholdingButtonGroup
        function ThresholdingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThresholdingButtonGroup.SelectedObject;

            str= app.Data.classiObj.outputArg;

            switch selectedButton.Text
                case 'Maximum probability'

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)==0
                        str=[str 'maxproba'];
                    end

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)
                        str=setxor(str,'adaptivethreshold','stable');
                    end

                    pix=find(matches(str,'threshold'));
                    if numel(pix)
                        val=str(pix+1);
                        str=setxor(str,val{1},'stable');
                        str=setxor(str,'threshold','stable');
                    end

                case 'Adaptive threshold (Otsu method)'

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)==0
                        str=[str 'adaptivethreshold'];
                    end

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)
                        str=setxor(str,'maxproba','stable');
                    end

                    pix=find(matches(str,'threshold'));

                    if numel(pix)
                        val=str(pix+1);
                        str=setxor(str,val{1},'stable');
                        str=setxor(str,'threshold','stable');
                    end


                case 'Fixed threshold'

                    pix=find(matches(str,'threshold'));

                    if numel(pix)==0
                        str=[str 'threshold' num2str(app.ValueEditField.Value)];
                    end

                    pix=find(matches(str,'maxproba'));
                    if numel(pix)
                        str=setxor(str,'maxproba','stable');
                    end

                    pix=find(matches(str,'adaptivethreshold'));
                    if numel(pix)
                        str=setxor(str,'adaptivethreshold','stable');
                    end

            end

            app.Data.classiObj.outputArg=str;
            checkStatus(app,false)
            displayClassi(app);

        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
            value = app.ValueEditField.Value;

            str= app.Data.classiObj.outputArg;

            pix=find(matches(str,'threshold'));
            if numel(pix)
                val=str(pix+1);
                str{pix+1}=num2str(value);
                app.Data.classiObj.outputArg=str;
                checkStatus(app,false)
                displayClassi(app);
            end

        end

        % Menu selected function: LoadclassifierMenu
        function LoadclassifierMenuSelected(app, event)

            classiObj=app.Data.classiObj;
            classifier=classiObj.loadClassifier;
            %assignin('base',classiObj.strid,classifier);
            checkStatus(app)
        end

        % Menu selected function: CheckstatusMenu
        function CheckstatusMenuSelected(app, event)
            checkStatus(app)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if app.isRefreshing, return; end

            classiObj=app.Data.classiObj;
            value=app.ChannelListBox.Value;

            if numel(app.ChannelListBoxSel.Items)==0 | matches(app.ChannelListBoxSel.Items,value)==false
                app.ChannelListBoxSel.Items=[app.ChannelListBoxSel.Items value];
            end

            classiObj.channelName= app.ChannelListBoxSel.Items;
            app.Data.classiObj=classiObj;
            displayClassi(app);

        end

        % Button pushed function: DeSelectButton
        function DeSelectButtonPushed(app, event)
            if app.isRefreshing, return; end

            classiObj=app.Data.classiObj;
            value=app.ChannelListBoxSel.Value;


            app.ChannelListBoxSel.Items=setxor(app.ChannelListBoxSel.Items,value);

            classiObj.channelName= app.ChannelListBoxSel.Items;
            app.Data.classiObj=classiObj;
            displayClassi(app);
        end

        % Callback function
        function BackupcurrentclassifierversionMenuSelected(app, event)
           disp('Backuping current version of the classifier')

            d = uiprogressdlg(app.ClassifierUIFigure,'Title','Please Wait...',...
                'Message','Saving current classifier model and network');
            d.Value=0.33;

            if numel(app.UITableData.Data)
                selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
                app.Data.classiObj.trainingset=find(selectedfortraining');
            else
                app.Data.classiObj.trainingset =1:numel(app.Data.classiObj.roi);
            end

            app.Data.classiObj.trainingParam=app.SettrainingparametersTab.UserData;

            app.Data.classiObj.bk; % backs up classification and classifier
            
            d.Value=0.67;
            d.Message='Saving training parameters...';

            %a=app.Data.classiObj.path


            pause(0.5);
            close(d)

            checkStatus(app,true)
            
            startupFcn(app,app.Data.classiObj);

            displayProperties(app)
            
        end

        % Menu selected function: RestorepreviousclassifierMenu
        function RestorepreviousclassifierMenuSelected(app, event)
           bk = classifierBKGUI(app, app.Data.classiObj);
    uiwait(bk.TrainingrusselectionUIFigure);   % attendre que BKGUI ferme (restore ou cancel)

    % Si BKGUI a été fermé sans restore, bk peut être invalid -> protège
    didRestore = false;
    try
        didRestore = bk.Result.didRestore;
    catch
    end

    if didRestore
        app.refreshAll('RebuildTrainingParam', true);
    end
        end

        % Value changed function: SelectROIsEditField
        function SelectROIsEditFieldValueChanged(app, event)
            value = app.SelectROIsEditField.Value;
            
            value=str2num(value);
            
            t=size(app.UITableData.Data,1);
            value=intersect(value,1:t);
            
            if numel(value)==0
                return
            end
            
            dt=cell(1,t);
            dt(:)={false};
            dt(value)={true}; 
          
            app.UITableData.Data(:,1)=dt';

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
            
        end

        % Value changed function: ShuffleROIsfractionEditField
        function ShuffleROIsfractionEditFieldValueChanged(app, event)
            value = app.ShuffleROIsfractionEditField.Value;
            
            value=str2num(value);
            
            t=size(app.UITableData.Data,1);
            id=randperm(t);
            
            value=min(value,1);
            value=max(value,0);
            
            dt=cell(1,t);
            dt(:)={false};
            
            dt(id(1:round(value*t)))={true};
  
            app.UITableData.Data(:,1)=dt';

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.classiObj.trainingset=find(selectedfortraining');
            checkStatus(app,false)
            displayData(app);
            
        end

        % Button down function: ClassifierPropertiesTab
        function ClassifierPropertiesTabButtonDown(app, event)
          %  disp('ok')
        %h = app.TabGroup.Children(2)
       % pause(1)
       % app.TabGroup.SelectedTab = h;
       displayData(app);
       displayProperties(app)
        end

        % Button pushed function: ManageROIsformattrainingsetButton
        function ManageROIsformattrainingsetButtonPushed(app, event)
            h = app.TabGroup.Children(4);
            app.TabGroup.SelectedTab = h;
            displayData(app);
        end

        % Button pushed function: BackupButton
        function BackupButtonPushed(app, event)
             app.BackupcurrentclassifierversionMenuSelected;
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
           bk = classifierBKGUI(app, app.Data.classiObj);
    uiwait(bk.TrainingrusselectionUIFigure);

    didRestore = false;
    try
        didRestore = bk.Result.didRestore;
    catch
    end

    if didRestore
        app.refreshAll('RebuildTrainingParam', true);
    end
        end

        % Button pushed function: OpenfolderButton
        function OpenfolderButtonPushed(app, event)
            pth=app.Data.classiObj.getPath;
            if ispc
            eval(['!explorer "' pth '"']);
            else
disp('unable to display folder with this OS');
            end
        end

        % Button pushed function: DisplaysampleimagesButton
        function DisplaysampleimagesButtonPushed(app, event)
            classiObj=app.Data.classiObj;

            if classiObj.category~="Timeseries"
            [ k, himg]=classiObj.displayFormattedTrainingSet('Display','Nimages',9);
            else
            uialert(app.ClassifierUIFigure,'This is not avaiable for this type of classifier','Error')
            end
        end

        % Button pushed function: SetclassfierparametersButton
        function SetclassfierparametersButtonPushed(app, event)
                  h = app.TabGroup.Children(2);
            app.TabGroup.SelectedTab = h;
            displayData(app);
        end

        % Button pushed function: SettrainingparametersButton
        function SettrainingparametersButtonPushed(app, event)
                  h = app.TabGroup.Children(3);
            app.TabGroup.SelectedTab = h;
            displayData(app);
        end

        % Value changed function: UsercommentsEditField_2
        function UsercommentsEditField_2ValueChanged(app, event)
            value = app.UsercommentsEditField_2.Value;
            app.Data.classiObj.description{2}=value;
             checkStatus(app,false)
            displayProperties(app);
        end

        % Button pushed function: FormattrainingsetfortrainingButton
        function FormattrainingsetfortrainingButtonPushed(app, event)
            FormattrainingsetMenuSelected(app, event);
        end

        % Button pushed function: TrainClassifierButton
        function TrainClassifierButtonPushed(app, event)
            TrainClassifierMenuSelected(app, event)
        end

        % Button pushed function: ValidateclassifierButton
        function ValidateclassifierButtonPushed(app, event)
            ValidateclassifierusingtestsetMenuSelected(app, event);
        end

        % Button pushed function: DisplayclassifierstatisticsButton
        function DisplayclassifierstatisticsButtonPushed(app, event)
            DisplaystatisticsMenuSelected(app, event)
        end

        % Button pushed function: displayROIsusedforvalidationButton
        function displayROIsusedforvalidationButtonPushed(app, event)
              h = app.TabGroup.Children(4);
            app.TabGroup.SelectedTab = h;
            displayData(app);
        end

        % Button pushed function: SaveclassifierButton
        function SaveclassifierButtonPushed(app, event)
            SaveclassifierMenuSelected(app, event)
        end

        % Callback function
        function CustomboundsCheckBoxValueChanged(app, event)
            value = app.CustomboundsCheckBox.Value;
            
            if value
                app.MaxframeEditField.Visible="on";
                app.MinframeEditField.Visible="on";
                app.ApplyrulestoallROIsButton.Visible="on";
                app.SetboundsselectionrulesButton.Visible="on";
            else
                 app.MaxframeEditField.Visible="off";
                app.MinframeEditField.Visible="off";
                app.ApplyrulestoallROIsButton.Visible="off";
                app.SetboundsselectionrulesButton.Visible="off";
            end
        end

        % Button pushed function: SetboundsselectionrulesButton
        function SetboundsselectionrulesButtonPushed(app, event)
              hBounds = annotationBoundsGUI(app.Data.classiObj);
    try
        uiwait(hBounds.UIFigure);
    catch
    end
    displayData(app);
    checkStatus(app,false);
        end

        % Button pushed function: DisplayaugmentedimagesButton
        function DisplayaugmentedimagesButtonPushed(app, event)
            classiObj = app.Data.classiObj;

    % 1) Vérifier que les paramètres de training sont sauvegardés
    if isequal(app.StatusSaved.Color, [1 0 0])
        uialert(app.ClassifierUIFigure, ...
            'Training parameters were not saved !', ...
            'Error');
        return;
    end

    % 2) Vérifier qu''il y a au moins une ROI dans le trainingset
    nrois = classiObj.trainingset;
    if numel(nrois) == 0
        uialert(app.ClassifierUIFigure, ...
            'You must select at least one ROI in the Dataset panel !', ...
            'Error');
        return;
    end

    % 3) Progress dialog
    d = uiprogressdlg(app.ClassifierUIFigure, ...
        'Title',   'Please wait...', ...
        'Message', 'Preparing augmentation preview...', ...
        'Indeterminate', 'on');  % plus simple ici, ou bien tu gères Value

    try
        % Optionnel : mise à jour de l'état de l''app
        checkStatus(app);

        % 4) Appel de la méthode de classi
        %    - Mode 'Augmentation' pour les deux figures (paires + types)
        %    - NAugPerType peut être ajusté
        classiObj.displayFormattedTrainingSet('Mode','Augmentation', ...
                                              'NAugPerType',4);

        % Si tu préfères voir aussi le montage RAW :
        % classiObj.displayFormattedTrainingSet('Display','Mode','raw');

    catch ME
        % En cas d'erreur on ferme la progress bar et on remonte l'erreur
        close(d);
        rethrow(ME);
    end

    % 5) Fin : on ferme la progress bar
    close(d);
        end

        % Callback function
        function DownValueChanged(app, event)
            value = app.PostprocessingDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ClassifierUIFigure = uifigure('Visible', 'off');
            app.ClassifierUIFigure.Name = 'Classifier';
            app.ClassifierUIFigure.Icon = fullfile(pathToMLAPP, 'brain.png');
            app.ClassifierUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassifierUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ClassifierUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveclassifierMenu = uimenu(app.FileMenu);
            app.SaveclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveclassifierMenuSelected, true);
            app.SaveclassifierMenu.Text = 'Save classifier';
            app.RestorepreviousclassifierMenu = uimenu(app.FileMenu);
            app.RestorepreviousclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @RestorepreviousclassifierMenuSelected, true);
            app.RestorepreviousclassifierMenu.Text = 'Restore previous classifier...';
            app.ClassifierMenu = uimenu(app.ClassifierUIFigure);
            app.ClassifierMenu.Text = 'Classifier';
            app.FormattrainingsetMenu = uimenu(app.ClassifierMenu);
            app.FormattrainingsetMenu.MenuSelectedFcn = createCallbackFcn(app, @FormattrainingsetMenuSelected, true);
            app.FormattrainingsetMenu.Text = 'Format training set...';
            app.TrainClassifierMenu = uimenu(app.ClassifierMenu);
            app.TrainClassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainClassifierMenuSelected, true);
            app.TrainClassifierMenu.Text = 'Train Classifier...';
            app.ValidateclassifierusingtestsetMenu = uimenu(app.ClassifierMenu);
            app.ValidateclassifierusingtestsetMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidateclassifierusingtestsetMenuSelected, true);
            app.ValidateclassifierusingtestsetMenu.Text = 'Validate classifier using test set...';
            app.DisplaystatisticsMenu = uimenu(app.ClassifierMenu);
            app.DisplaystatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaystatisticsMenuSelected, true);
            app.DisplaystatisticsMenu.Text = 'Display statistics...';
            app.LoadclassifierMenu = uimenu(app.ClassifierMenu);
            app.LoadclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadclassifierMenuSelected, true);
            app.LoadclassifierMenu.Text = 'Load classifier';
            app.CheckstatusMenu = uimenu(app.ClassifierMenu);
            app.CheckstatusMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckstatusMenuSelected, true);
            app.CheckstatusMenu.Separator = 'on';
            app.CheckstatusMenu.Text = 'Check status...';
            app.TabGroup = uitabgroup(app.ClassifierUIFigure);
            app.ClassifierPropertiesTab = uitab(app.TabGroup);
            app.ClassifierPropertiesTab.Title = 'Classifier Properties';
            app.ClassifierPropertiesTab.ButtonDownFcn = createCallbackFcn(app, @ClassifierPropertiesTabButtonDown, true);
            app.ClassifierquickinfoPanel = uipanel(app.ClassifierPropertiesTab);
            app.ClassifierquickinfoPanel.Title = 'Classifier quick info';
            app.PathEditFieldLabel = uilabel(app.ClassifierquickinfoPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.ClassifierquickinfoPanel, 'text');
            app.PathEditField.Editable = 'off';
            app.TypeEditFieldLabel = uilabel(app.ClassifierquickinfoPanel);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.ClassifierquickinfoPanel, 'text');
            app.TypeEditField.Editable = 'off';
            app.ClassesEditFieldLabel = uilabel(app.ClassifierquickinfoPanel);
            app.ClassesEditFieldLabel.Text = 'Classes';
            app.ClassesEditField = uieditfield(app.ClassifierquickinfoPanel, 'text');
            app.ClassesEditField.Editable = 'off';
            app.backupversionsEditFieldLabel = uilabel(app.ClassifierquickinfoPanel);
            app.backupversionsEditFieldLabel.Text = 'backup versions:';
            app.backupversionsEditField = uieditfield(app.ClassifierquickinfoPanel, 'text');
            app.backupversionsEditField.Editable = 'off';
            app.RestoreButton = uibutton(app.ClassifierquickinfoPanel, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Text = 'Restore...';
            app.BackupButton = uibutton(app.ClassifierquickinfoPanel, 'push');
            app.BackupButton.ButtonPushedFcn = createCallbackFcn(app, @BackupButtonPushed, true);
            app.BackupButton.Text = 'Back up!';
            app.OpenfolderButton = uibutton(app.ClassifierquickinfoPanel, 'push');
            app.OpenfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfolderButtonPushed, true);
            app.OpenfolderButton.Text = 'Open folder...';
            app.SetclassfierparametersButton = uibutton(app.ClassifierquickinfoPanel, 'push');
            app.SetclassfierparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetclassfierparametersButtonPushed, true);
            app.SetclassfierparametersButton.Tooltip = {'Click his button to define the type of classifier'};
            app.SetclassfierparametersButton.Text = 'Set classfier parameters...';
            app.UsercommentsEditFieldLabel = uilabel(app.ClassifierquickinfoPanel);
            app.UsercommentsEditFieldLabel.Text = 'User comments';
            app.UsercommentsEditField = uieditfield(app.ClassifierquickinfoPanel, 'text');
            app.UsercommentsEditField.Editable = 'off';
            app.TrainingvalidationsetPanel = uipanel(app.ClassifierPropertiesTab);
            app.TrainingvalidationsetPanel.Title = 'Training & validation set';
            app.NumberofROIsusedfortrainingvalidationLabel = uilabel(app.TrainingvalidationsetPanel);
            app.NumberofROIsusedfortrainingvalidationLabel.Text = 'Number of ROIs used for training or validation:';
            app.NumberROIS = uieditfield(app.TrainingvalidationsetPanel, 'text');
            app.NumberROIS.Editable = 'off';
            app.NumberROIS.Tooltip = {'You must have at least one ROI to proceed with the training procedure'};
            app.ManageROIsformattrainingsetButton = uibutton(app.TrainingvalidationsetPanel, 'push');
            app.ManageROIsformattrainingsetButton.ButtonPushedFcn = createCallbackFcn(app, @ManageROIsformattrainingsetButtonPushed, true);
            app.ManageROIsformattrainingsetButton.Text = 'Manage ROIs & format trainingset...';
            app.NumberofROIsusedfortrainingvalidationLabel_2 = uilabel(app.TrainingvalidationsetPanel);
            app.NumberofROIsusedfortrainingvalidationLabel_2.Text = 'Fraction of ROIs for training vs validation:';
            app.NumberROIS_2 = uieditfield(app.TrainingvalidationsetPanel, 'text');
            app.NumberROIS_2.Editable = 'off';
            app.NumberROIS_2.Tooltip = {'Some ROIs should be left for th validaton procedure'};
            app.formatLabel = uilabel(app.TrainingvalidationsetPanel);
            app.formatLabel.Tooltip = {'If there is no formated image, youmust formt the training set bfor traning.'};
            app.formatLabel.Text = 'formatLabel';
            app.DisplaysampleimagesButton = uibutton(app.TrainingvalidationsetPanel, 'push');
            app.DisplaysampleimagesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaysampleimagesButtonPushed, true);
            app.DisplaysampleimagesButton.Tooltip = {'Format training set is mandatory before training; Press this button if there are no formatted images'};
            app.DisplaysampleimagesButton.Text = 'Display sample images';
            app.SettrainingparametersButton = uibutton(app.TrainingvalidationsetPanel, 'push');
            app.SettrainingparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SettrainingparametersButtonPushed, true);
            app.SettrainingparametersButton.Text = 'Set training parameters...';
            app.AvailableformattedtrainingsetLabel = uilabel(app.TrainingvalidationsetPanel);
            app.AvailableformattedtrainingsetLabel.Text = 'Available formatted trainingset: ';
            app.TrainClassifierButton = uibutton(app.TrainingvalidationsetPanel, 'push');
            app.TrainClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @TrainClassifierButtonPushed, true);
            app.TrainClassifierButton.Text = 'Train Classifier...';
            app.DisplayaugmentedimagesButton = uibutton(app.TrainingvalidationsetPanel, 'push');
            app.DisplayaugmentedimagesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayaugmentedimagesButtonPushed, true);
            app.DisplayaugmentedimagesButton.Text = 'Display augmented images';
            app.OutputandstatisticsPanel = uipanel(app.ClassifierPropertiesTab);
            app.OutputandstatisticsPanel.Title = 'Output and statistics';
            app.DisplayclassifierstatisticsButton = uibutton(app.OutputandstatisticsPanel, 'push');
            app.DisplayclassifierstatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayclassifierstatisticsButtonPushed, true);
            app.DisplayclassifierstatisticsButton.Text = 'Display classifier statistics....';
            app.ValidateclassifierButton = uibutton(app.OutputandstatisticsPanel, 'push');
            app.ValidateclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateclassifierButtonPushed, true);
            app.ValidateclassifierButton.Text = 'Validate classifier....';
            app.displayROIsusedforvalidationButton = uibutton(app.OutputandstatisticsPanel, 'push');
            app.displayROIsusedforvalidationButton.ButtonPushedFcn = createCallbackFcn(app, @displayROIsusedforvalidationButtonPushed, true);
            app.displayROIsusedforvalidationButton.Text = 'display ROIs used for validation...';
            app.NumberofannotatedROIsEditFieldLabel = uilabel(app.OutputandstatisticsPanel);
            app.NumberofannotatedROIsEditFieldLabel.Text = 'Number of annotated ROIs:';
            app.NumberofannotatedROIsEditField = uieditfield(app.OutputandstatisticsPanel, 'text');
            app.NumberofannotatedROIsEditField.Editable = 'off';
            app.AnnotatedROIswithvalidationdataEditFieldLabel = uilabel(app.OutputandstatisticsPanel);
            app.AnnotatedROIswithvalidationdataEditFieldLabel.Text = 'Annotated ROIs with validation data:';
            app.AnnotatedROIswithvalidationdataEditField = uieditfield(app.OutputandstatisticsPanel, 'text');
            app.AnnotatedROIswithvalidationdataEditField.Editable = 'off';
            app.Label_3 = uilabel(app.ClassifierPropertiesTab);
            app.Label_3.Text = '1';
            app.Label_4 = uilabel(app.ClassifierPropertiesTab);
            app.Label_4.Text = '2';
            app.Label_5 = uilabel(app.ClassifierPropertiesTab);
            app.Label_5.Text = '3';
            app.SetclassifierparametersTab = uitab(app.TabGroup);
            app.SetclassifierparametersTab.Title = 'Set classifier parameters';
            app.TypeLabel = uilabel(app.SetclassifierparametersTab);
            app.TypeLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.SetclassifierparametersTab);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.SpaceseparatedclassnamesLabel = uilabel(app.SetclassifierparametersTab);
            app.SpaceseparatedclassnamesLabel.Text = 'Space-separated class names: ';
            app.SpaceseparatedclassnamesEditField = uieditfield(app.SetclassifierparametersTab, 'text');
            app.SpaceseparatedclassnamesEditField.ValueChangedFcn = createCallbackFcn(app, @SpaceseparatedclassnamesEditFieldValueChanged, true);
            app.SpaceseparatedclassnamesEditField.Tooltip = {'For classification, class definition is mandaztory; If emtpy, the classifier will output a regression'};
            app.PostprocessingcustomfunctionhandleEditFieldLabel = uilabel(app.SetclassifierparametersTab);
            app.PostprocessingcustomfunctionhandleEditFieldLabel.Text = 'Post-processing custom function handle:';
            app.PostprocessingcustomfunctionhandleEditField = uieditfield(app.SetclassifierparametersTab, 'text');
            app.PostprocessingcustomfunctionhandleEditField.ValueChangedFcn = createCallbackFcn(app, @PostprocessingcustomfunctionhandleEditFieldValueChanged2, true);
            app.ClassiDetailsLabel = uilabel(app.SetclassifierparametersTab);
            app.ClassiDetailsLabel.Text = 'ClassiDetails';
            app.PostprocessingDropDownLabel = uilabel(app.SetclassifierparametersTab);
            app.PostprocessingDropDownLabel.Text = 'Post-processing';
            app.PostprocessingDropDown = uidropdown(app.SetclassifierparametersTab);
            app.PostprocessingDropDown.Items = {'plain output / probabilities for each class', 'plain output / semantic segmentation', 'postprocessing', 'custom function (see below)'};
            app.PostprocessingDropDown.ValueChangedFcn = createCallbackFcn(app, @PostprocessingDropDownValueChanged, true);
            app.PostprocessingDropDown.Value = 'plain output / probabilities for each class';
            app.PostprocessingparametersPanel = uipanel(app.SetclassifierparametersTab);
            app.PostprocessingparametersPanel.Title = 'Post-processing parameters';
            app.WatershedCheckBox = uicheckbox(app.PostprocessingparametersPanel);
            app.WatershedCheckBox.ValueChangedFcn = createCallbackFcn(app, @WatershedCheckBoxValueChanged, true);
            app.WatershedCheckBox.Tooltip = {'Applies a watershed segmentation on the output'};
            app.WatershedCheckBox.Text = 'Watershed';
            app.ThresholdingButtonGroup = uibuttongroup(app.PostprocessingparametersPanel);
            app.ThresholdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingButtonGroupSelectionChanged, true);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.FixedthresholdButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.FixedthresholdButton.Text = 'Fixed threshold';
            app.FixedthresholdButton.Value = true;
            app.AdaptivethresholdOtsumethodButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.AdaptivethresholdOtsumethodButton.Text = 'Adaptive threshold (Otsu method)';
            app.MaximumprobabilityButton = uiradiobutton(app.ThresholdingButtonGroup);
            app.MaximumprobabilityButton.Text = 'Maximum probability';
            app.ValueEditFieldLabel = uilabel(app.ThresholdingButtonGroup);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.ValueEditField = uieditfield(app.ThresholdingButtonGroup, 'text');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.ValueEditField.Value = '0.9';
            app.FilteroutsmallobjectsCheckBox = uicheckbox(app.PostprocessingparametersPanel);
            app.FilteroutsmallobjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilteroutsmallobjectsCheckBoxValueChanged, true);
            app.FilteroutsmallobjectsCheckBox.Tooltip = {'Applies a watershed segmentation on the output'};
            app.FilteroutsmallobjectsCheckBox.Text = 'Filter out small objects';
            app.SizepixelEditFieldLabel = uilabel(app.PostprocessingparametersPanel);
            app.SizepixelEditFieldLabel.Text = 'Size (pixel):';
            app.SizepixelEditField = uieditfield(app.PostprocessingparametersPanel, 'numeric');
            app.SizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @SizepixelEditFieldValueChanged, true);
            app.AvailablechannelstakenfromtrainingdatasetROIsLabel = uilabel(app.SetclassifierparametersTab);
            app.AvailablechannelstakenfromtrainingdatasetROIsLabel.Text = 'Available channels (taken from training dataset (ROIs):';
            app.ChannelListBox = uilistbox(app.SetclassifierparametersTab);
            app.ChannelListBox.Items = {};
            app.ChannelListBox.Tooltip = {'This is only activated after ROIs have been imported to this classifier (see tab #3 in this GUI).'};
            app.ChannelListBox.Value = {};
            app.SelectedchannelsasclassificationinputLabel = uilabel(app.SetclassifierparametersTab);
            app.SelectedchannelsasclassificationinputLabel.Text = 'Selected channels as classification input:';
            app.ChannelListBoxSel = uilistbox(app.SetclassifierparametersTab);
            app.ChannelListBoxSel.Items = {};
            app.ChannelListBoxSel.Tooltip = {'It is not allowed to have more than 3 channels for all classification schemes, except the U-Net model'};
            app.ChannelListBoxSel.Value = {};
            app.SelectButton = uibutton(app.SetclassifierparametersTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = '>';
            app.DeSelectButton = uibutton(app.SetclassifierparametersTab, 'push');
            app.DeSelectButton.ButtonPushedFcn = createCallbackFcn(app, @DeSelectButtonPushed, true);
            app.DeSelectButton.Text = '<';
            app.UsercommentsEditField_2Label = uilabel(app.SetclassifierparametersTab);
            app.UsercommentsEditField_2Label.Text = 'User comments:';
            app.UsercommentsEditField_2 = uieditfield(app.SetclassifierparametersTab, 'text');
            app.UsercommentsEditField_2.ValueChangedFcn = createCallbackFcn(app, @UsercommentsEditField_2ValueChanged, true);
            app.SettrainingparametersTab = uitab(app.TabGroup);
            app.SettrainingparametersTab.Title = 'Set training parameters';
            app.SettrainingparametersTab.ButtonDownFcn = createCallbackFcn(app, @SettrainingparametersTabButtonDown, true);
            app.SettrainingandvalidationsetROIsTab = uitab(app.TabGroup);
            app.SettrainingandvalidationsetROIsTab.Title = 'Set training and validation set (ROIs)';
            app.SettrainingandvalidationsetROIsTab.ButtonDownFcn = createCallbackFcn(app, @SettrainingandvalidationsetROIsTabButtonDown, true);
            app.SettrainingandvalidationsetROIsTab.Tag = 'list';
            app.UITableData = uitable(app.SettrainingandvalidationsetROIsTab);
            app.UITableData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData.RowName = {};
            app.UITableData.CellEditCallback = createCallbackFcn(app, @UITableDataCellEdit, true);
            app.UITableData.CellSelectionCallback = createCallbackFcn(app, @UITableDataCellSelection, true);
            app.ImportROIsButton = uibutton(app.SettrainingandvalidationsetROIsTab, 'push');
            app.ImportROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportROIsButtonPushed, true);
            app.ImportROIsButton.Text = 'Import ROIs...';
            app.AnnotateselectedROIButton = uibutton(app.SettrainingandvalidationsetROIsTab, 'push');
            app.AnnotateselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateselectedROIButtonPushed, true);
            app.AnnotateselectedROIButton.Tooltip = {'Select a ROI in the ROI Index column first'};
            app.AnnotateselectedROIButton.Text = 'Annotate selected ROI...';
            app.SelectallButton = uibutton(app.SettrainingandvalidationsetROIsTab, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.SettrainingandvalidationsetROIsTab, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.removeselectedROIButton = uibutton(app.SettrainingandvalidationsetROIsTab, 'push');
            app.removeselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @removeselectedROIButtonPushed, true);
            app.removeselectedROIButton.Text = 'remove selected ROI';
            app.SelectROIsEditFieldLabel = uilabel(app.SettrainingandvalidationsetROIsTab);
            app.SelectROIsEditFieldLabel.Text = 'Select ROIs #';
            app.SelectROIsEditField = uieditfield(app.SettrainingandvalidationsetROIsTab, 'text');
            app.SelectROIsEditField.ValueChangedFcn = createCallbackFcn(app, @SelectROIsEditFieldValueChanged, true);
            app.ShuffleROIsfractionEditFieldLabel = uilabel(app.SettrainingandvalidationsetROIsTab);
            app.ShuffleROIsfractionEditFieldLabel.Text = 'Shuffle ROIs fraction';
            app.ShuffleROIsfractionEditField = uieditfield(app.SettrainingandvalidationsetROIsTab, 'text');
            app.ShuffleROIsfractionEditField.ValueChangedFcn = createCallbackFcn(app, @ShuffleROIsfractionEditFieldValueChanged, true);
            app.ShuffleROIsfractionEditField.Value = '1';
            app.FormattrainingsetfortrainingButton = uibutton(app.SettrainingandvalidationsetROIsTab, 'push');
            app.FormattrainingsetfortrainingButton.ButtonPushedFcn = createCallbackFcn(app, @FormattrainingsetfortrainingButtonPushed, true);
            app.FormattrainingsetfortrainingButton.Text = 'Format training set for training...';
            app.SetboundsselectionrulesButton = uibutton(app.SettrainingandvalidationsetROIsTab, 'push');
            app.SetboundsselectionrulesButton.ButtonPushedFcn = createCallbackFcn(app, @SetboundsselectionrulesButtonPushed, true);
            app.SetboundsselectionrulesButton.Text = 'Set bounds selection rules....';
            app.BoundsnoticeLabel = uilabel(app.SettrainingandvalidationsetROIsTab);
            app.BoundsnoticeLabel.Text = 'Bounds notice';
            app.StatusSaved = uilamp(app.ClassifierUIFigure);
            app.StatusSaved.Tooltip = {'Classifier status'};
            app.StatusLoad = uilamp(app.ClassifierUIFigure);
            app.StatusLoad.Tooltip = {'Classifier loaded in memory'};
            app.ParametersaresavedLabel = uilabel(app.ClassifierUIFigure);
            app.ParametersaresavedLabel.Text = 'Parameters are saved:';
            app.ClassifierisloadedinworksapcememoryLabel = uilabel(app.ClassifierUIFigure);
            app.ClassifierisloadedinworksapcememoryLabel.Text = 'Classifier is loaded in worksapce (memory):';
            app.SaveclassifierButton = uibutton(app.ClassifierUIFigure, 'push');
            app.SaveclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @SaveclassifierButtonPushed, true);
            app.SaveclassifierButton.Text = 'Save classifier';
            % Show the figure after all components are created
            app.ClassifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassifierUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassifierUIFigure)
        end
    end
end