classdef classifierImporterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassifierUIFigure       matlab.ui.Figure
        TransferparametersPanel  matlab.ui.container.Panel
        TransferunformattedtrainingsetROIsCheckBox  matlab.ui.control.CheckBox
        TransferformattedgroundtruthimagesCheckBox  matlab.ui.control.CheckBox
        TransfertrainedclassifierfileCheckBox  matlab.ui.control.CheckBox
        TransfertrainingparametersCheckBox  matlab.ui.control.CheckBox
        OuputclassificationpathnamePanel  matlab.ui.container.Panel
        EnternewclassifiernameEditField  matlab.ui.control.EditField
        EnternewclassifiernameEditFieldLabel  matlab.ui.control.Label
        SetclassifierpathLabel   matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        ProceedButton            matlab.ui.control.Button
        ClassifiercreationmethodButtonGroup  matlab.ui.container.ButtonGroup
        selectedClassifierLabel  matlab.ui.control.Label
        ChooseamongclassifiersopenintheworkspaceDropDown  matlab.ui.control.DropDown
        ChooseamongclassifiersopenintheworkspaceDropDownLabel  matlab.ui.control.Label
        CloneselectaclassifierfromtherepositoryButton  matlab.ui.control.RadioButton
        CloneanexistingclassiferintheworkspaceButton  matlab.ui.control.RadioButton
        CreateablankclassifiernotrecommendedButton  matlab.ui.control.RadioButton
    end


    properties (Access = private)
        callingApp % Description
        inputProject % Description
    end
    
    properties (Access = public)
        Data % Description
    end

    methods (Access = private)
        
        function getAvailableClassi(app,option)
            
            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            cc=0;
            cd=0;
            app.Data={};

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});


                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};

                    for k=1:numel(tmp.processing.classification)
                        %  k
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];
                    end

                    st.Projectclassi{cc}=tmpclassi;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        tmpproj = [tmpproj tmp.fov(k).id];
                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')
                    
                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};
                   % aa= st.Classifier{cd}
                end

            end

          %  st
            app.Data=st;
            app.Data.convert={};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, shallowObj)
            app.callingApp=callingApp;
           
            app.inputProject.shallowObj=shallowObj;
             app.TransferparametersPanel.Enable='off';
            if numel(app.inputProject.shallowObj)==0
                app.SetclassifierpathLabel.Enable='on';
                app.Button.Enable='on';
                app.ProceedButton.Enable='off';
                app.ClassifierUIFigure.Name='Creating a new independent classifier';
            else
                 app.ClassifierUIFigure.Name='Creating a project-dependent classifier';
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)
        end

        % Selection changed function: ClassifiercreationmethodButtonGroup
        function ClassifiercreationmethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.ClassifiercreationmethodButtonGroup.SelectedObject;

            if strcmp(selectedButton.Text,'Clone an existing classifer in the workspace')

                app.ChooseamongclassifiersopenintheworkspaceDropDown.Enable='on';
                app.TransferparametersPanel.Enable='on';
                % list all avaiable classi in the workspace
                
                getAvailableClassi(app,'workspace')
                s=app.Data;
                
                cc=1;
                displaystr={};
                varstr={};
                availableROIs=[];
                
                for i=1:numel(s.Projectclassi)
                    
                    proj=s.Project{i};
                    
                    shallowObj=evalin('base',proj);
                    
                    
                    for k=1:numel(s.Projectclassi{i})
                        varstr{cc}=[proj '.processing.classification(' num2str(k) ')'];
                        
                        classiObj=evalin('base',varstr{cc});
                        rois=numel(classiObj.roi);
                        if rois==1 & numel(classiObj.roi(1).id)==0
                            rois=0;
                        end
                        
                        displaystr{cc}=[proj '  //  ' s.Projectclassi{i}{k} ' // ' num2str(rois) ' ROIs available'  ];
                        availableROIs(cc)=rois;
                        cc=cc+1;
                    end
                end

                for i=1:numel(s.Classifier)
                    
                    clas=s.Classifier{i};
                    varstr{cc}=clas;
                    
                    classiObj=evalin('base',varstr{cc});
                        rois=numel(classiObj.roi);
                        if rois==1 & numel(classiObj.roi(1).id)==0
                            rois=0;
                        end
                        
                    displaystr{cc}=[clas ' // ' num2str(rois) ' ROIs available'  ];
                    availableROIs(cc)=rois;
                   cc=cc+1;
                end
                
                app.ChooseamongclassifiersopenintheworkspaceDropDown.Items=displaystr;
                app.Data.varstr=varstr; % list of classifier variables in the workspace
                app.Data.displaystr=displaystr;
                app.Data.availableROIs=availableROIs;
                
        
            end

 if strcmp(selectedButton.Text,'Clone/select a classifier from the repository...')
classiRepository(app)

uiwait(app.ClassifierUIFigure);

pth=app.ClassifierUIFigure.UserData;

if numel(pth)==0 % user canceled selection
  app.ClassifiercreationmethodButtonGroup.SelectedObject=app.CreateablankclassifiernotrecommendedButton;
  app.selectedClassifierLabel.Text='User cancelled selection';
else
  [pth2 fle]=fileparts(pth);
app.selectedClassifierLabel.Text=['User selected : ' fle ' from repository'];
app.selectedClassifierLabel.Visible='on';
app.TransferparametersPanel.Enable='on';

end

 end
            if strcmp(selectedButton.Text,'New classifier')
                app.TransferparametersPanel.Enable='off';
                 app.ChooseamongclassifiersopenintheworkspaceDropDown.Enable='off';
             %   app.TransferparametersPanel.Enable='on';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.inputProject.path=[];
           % filename=app.EnternewclassifiernameEditField.Value;

            [filename,path,rep] = uiputfile('*.mat','File Selection',fullfile(pwd,['no_need_to_set_filename_here']));
            if isequal(filename,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected ', fullfile(path, filename)]);
            end
            %         end

            app.inputProject.path=path;
            app.SetclassifierpathLabel.Text=path;
            app.ProceedButton.Enable='on';
        end

        % Close request function: ClassifierUIFigure
        function ClassifierUIFigureCloseRequest(app, event)
            uiresume(app.callingApp.DetecDivUIFigure)
            delete(app)

        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

               d = uiprogressdlg(app.ClassifierUIFigure,'Title','Please Wait...',...
                    'Message','Creating new classifier...');
            d.Value=0.1;
            
            if numel(app.inputProject.shallowObj)==0 % independent classifier to be created
                    filename=app.EnternewclassifiernameEditField.Value;
                    path=app.inputProject.path;
                    classiObj=classiNew('path',path,'filename',filename); % creates and saves object
                    assignin('base',filename,classiObj);

                else % classifier is associated to project 
                    
                    filename=app.EnternewclassifiernameEditField.Value;
                    app.inputProject.shallowObj.addClassification('name',filename);
                    classiObj=app.inputProject.shallowObj.processing.classification(end);
            end

            
            if app.CloneanexistingclassiferintheworkspaceButton.Value==1 % classifier transfered from exisiting one
                % gathering classi variable
                vars=app.Data.varstr;
                displaystr=app.Data.displaystr;
                selectedvarstr=app.ChooseamongclassifiersopenintheworkspaceDropDown.Value;
                ok=0;
                for i=1:numel(displaystr)
                    
                if strcmp(displaystr{i},selectedvarstr)
                    ok=1;
                    break
                end
                end
                if ok==0
                   uialert(app.DetecDivUIFigure,['Could not find item in the list!'] ,'Warning','Icon','warning');
                end
                selectedvar=evalin('base',vars{i});
                
                % gathering transfer options
                option=[];
                option(1)=app.TransfertrainingparametersCheckBox.Value;
                option(2)=app.TransfertrainedclassifierfileCheckBox.Value;
                option(3)=app.TransferformattedgroundtruthimagesCheckBox.Value;
                option(4)=app.TransferunformattedtrainingsetROIsCheckBox.Value;
                
                
                if numel(app.inputProject.shallowObj)==0
                    %classiObj
                else
                    classiObj=app.inputProject.shallowObj.processing.classification(end);  
                end
                
                d.Value=0.3;
                d.Message='Importing selected ROIs.... Please wait...';


             option2=option;
             option2(4)=0; % roi iport should not be done  with importFromClassi 
                 classiObj.importFromClassi(selectedvar,option2);
                 app.Data.classiObj=classiObj; % this is required before calling roiImporterGUI to pass the classifier
            

                 if option(4)==1
                 roiImporterGUI(app, selectedvar.strid) % pass the name of the classifier to narrow down the selection
                 uiwait(app.ClassifierUIFigure);
                 end
                %classiSave(classiObj);
        
            end

             if app.CloneselectaclassifierfromtherepositoryButton.Value==1 % clone classifier from repository
                % gathering classi variable

                pth=app.ClassifierUIFigure.UserData;
               classiFromRepository=classiLoad(pth);

             %  if numel(classiFromRepository)==0
             %           uialert(app.ClassifierUIFigure,'Could not load the classififer from the repository. It is like')
            %   end

               assignin('base','classiFromRepository',classiFromRepository);
                
                % gathering transfer options
                option=[];
                option(1)=app.TransfertrainingparametersCheckBox.Value;
                option(2)=app.TransfertrainedclassifierfileCheckBox.Value;
                option(3)=app.TransferformattedgroundtruthimagesCheckBox.Value;
                option(4)=app.TransferunformattedtrainingsetROIsCheckBox.Value;
                
                
                if numel(app.inputProject.shallowObj)==0
                    %classiObj
                else
                    classiObj=app.inputProject.shallowObj.processing.classification(end);  
                end
                
                d.Value=0.3;
                d.Message='Importing selected ROIs.... Please wait...';


             option2=option;
             option2(4)=0; % roi iport should not be done  with importFromClassi 
                 classiObj.importFromClassi( classiFromRepository,option2);
                 app.Data.classiObj=classiObj; % this is required before calling roiImporterGUI; 

                 if option(4)==1
                 roiImporterGUI(app,  classiFromRepository.strid) % pass the name of the 
                 uiwait(app.ClassifierUIFigure);
                 end
                %classiSave(classiObj);
                 evalin('base','clear classiFromRepository');

             end


             d.Value=0.66;
              d.Message='Saving classifier.... Please wait...';
              
            classiSave(classiObj);
            
            d.Message='Done !';
            d.Value=1;
            pause(1);
            close(d);
            uiresume(app.callingApp.DetecDivUIFigure);
            
           %  if app.CreateablankclassifiernotrecommendedButton.Value==1 % new classifier from scratch
                 classifierGUI(classiObj) ; % laucnes classifier GUI 
          %   end
             
            delete(app)
        end

        % Value changed function: 
        % ChooseamongclassifiersopenintheworkspaceDropDown
        function ChooseamongclassifiersopenintheworkspaceDropDownValueChanged(app, event)
            value = app.ChooseamongclassifiersopenintheworkspaceDropDown.Value;
           % pix=find(contains(app.ChooseamongclassifiersopenintheworkspaceDropDown.Items,value));
           % app.EnterROIindicesEditField.Value=['1:' num2str(app.Data.availableROIs(pix))];
        end

        % Value changed function: 
        % TransferunformattedtrainingsetROIsCheckBox
        function TransferunformattedtrainingsetROIsCheckBoxValueChanged(app, event)
            value = app.TransferunformattedtrainingsetROIsCheckBox.Value;
            
            if value==1
          %  app.EnterROIindicesEditField.Enable='on';
           % app.PreserveannotationsCheckBox.Enable='on';
            else
          %  app.EnterROIindicesEditField.Enable='off';    
        %    app.PreserveannotationsCheckBox.Enable='off';
            end
            
        end

        % Callback function
        function PreserveannotationsCheckBoxValueChanged(app, event)
            value = app.PreserveannotationsCheckBox.Value;
         
 
                vars=app.Data.varstr;
                displaystr=app.Data.displaystr;
                selectedvarstr=app.ChooseamongclassifiersopenintheworkspaceDropDown.Value;
                ok=0;
                for i=1:numel(displaystr)
                    
                if strcmp(displaystr{i},selectedvarstr)
                    ok=1;
                    break
                end
                end
                if ok==0
                   uialert(app.DetecDivUIFigure,['Could not find item in the list!'] ,'Warning','Icon','warning');
                end
                tmp=evalin('base',vars{i});


            if value==1 % preserve training
            app.ClassesnamesindestinationEditField.Enable='on';
            app.ClassesnamesinsourceEditField.Enable='on';
            
            str=[];
            for i=1:numel(tmp.classes)
                str=[str ' ' tmp.classes{i}];
            end
            
            str2=str;
%             for i=1:numel(app.Data.mainApp.Data.classiObj.classes)
%                 str2=[str2 ' ' app.Data.mainApp.Data.classiObj.classes{i}];
%             end
            
            app.ClassesnamesinsourceEditField.Value=str;
            app.ClassesnamesindestinationEditField.Value=str2;
            app.Data.convert={str,str2};

            else
            app.ClassesnamesindestinationEditField.Enable='off';
            app.ClassesnamesinsourceEditField.Enable='off';
             app.Data.convert={};
            end
            
        end

        % Callback function
        function ClassesnamesindestinationEditFieldValueChanged(app, event)
            str2 = app.ClassesnamesindestinationEditField.Value;
            
              str=app.ClassesnamesinsourceEditField.Value;
            
            app.Data.convert={str,str2};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassifierUIFigure = uifigure('Visible', 'off');
            app.ClassifierUIFigure.Name = 'MATLAB App';
            app.ClassifierUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassifierUIFigureCloseRequest, true);
            app.ClassifiercreationmethodButtonGroup = uibuttongroup(app.ClassifierUIFigure);
            app.ClassifiercreationmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClassifiercreationmethodButtonGroupSelectionChanged, true);
            app.ClassifiercreationmethodButtonGroup.Title = 'Classifier creation method';
            app.CreateablankclassifiernotrecommendedButton = uiradiobutton(app.ClassifiercreationmethodButtonGroup);
            app.CreateablankclassifiernotrecommendedButton.Text = 'Create a blank classifier (not recommended)';
            app.CreateablankclassifiernotrecommendedButton.Value = true;
            app.CloneanexistingclassiferintheworkspaceButton = uiradiobutton(app.ClassifiercreationmethodButtonGroup);
            app.CloneanexistingclassiferintheworkspaceButton.Text = 'Clone an existing classifer in the workspace';
            app.CloneselectaclassifierfromtherepositoryButton = uiradiobutton(app.ClassifiercreationmethodButtonGroup);
            app.CloneselectaclassifierfromtherepositoryButton.Tooltip = {'Click this button to select a classifer from the repository'};
            app.CloneselectaclassifierfromtherepositoryButton.Text = 'Clone/select a classifier from the repository...';
            app.ChooseamongclassifiersopenintheworkspaceDropDownLabel = uilabel(app.ClassifiercreationmethodButtonGroup);
            app.ChooseamongclassifiersopenintheworkspaceDropDownLabel.Enable = 'off';
            app.ChooseamongclassifiersopenintheworkspaceDropDownLabel.Text = 'Choose among classifiers open in the workspace:';
            app.ChooseamongclassifiersopenintheworkspaceDropDown = uidropdown(app.ClassifiercreationmethodButtonGroup);
            app.ChooseamongclassifiersopenintheworkspaceDropDown.Items = {'Classifier1', ''};
            app.ChooseamongclassifiersopenintheworkspaceDropDown.Editable = 'on';
            app.ChooseamongclassifiersopenintheworkspaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseamongclassifiersopenintheworkspaceDropDownValueChanged, true);
            app.ChooseamongclassifiersopenintheworkspaceDropDown.Enable = 'off';
            app.ChooseamongclassifiersopenintheworkspaceDropDown.Value = 'Classifier1';
            app.selectedClassifierLabel = uilabel(app.ClassifiercreationmethodButtonGroup);
            app.selectedClassifierLabel.Visible = 'off';
            app.selectedClassifierLabel.Text = 'selectedClassifier';
            app.ProceedButton = uibutton(app.ClassifierUIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.ClassifierUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OuputclassificationpathnamePanel = uipanel(app.ClassifierUIFigure);
            app.OuputclassificationpathnamePanel.Title = 'Ouput classification path & name';
            app.Button = uibutton(app.OuputclassificationpathnamePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = '...';
            app.SetclassifierpathLabel = uilabel(app.OuputclassificationpathnamePanel);
            app.SetclassifierpathLabel.Enable = 'off';
            app.SetclassifierpathLabel.Tooltip = {'A path is required only when setting up an independent classifier'};
            app.SetclassifierpathLabel.Text = 'Set classifier path:';
            app.EnternewclassifiernameEditFieldLabel = uilabel(app.OuputclassificationpathnamePanel);
            app.EnternewclassifiernameEditFieldLabel.Text = 'Enter new classifier name:';
            app.EnternewclassifiernameEditField = uieditfield(app.OuputclassificationpathnamePanel, 'text');
            app.EnternewclassifiernameEditField.Value = 'my_classi';
            app.TransferparametersPanel = uipanel(app.ClassifierUIFigure);
            app.TransferparametersPanel.Tooltip = {'Specify transfer parameters in case a classifier is transferred to the new classifier'};
            app.TransferparametersPanel.Title = 'Transfer parameters';
            app.TransfertrainingparametersCheckBox = uicheckbox(app.TransferparametersPanel);
            app.TransfertrainingparametersCheckBox.Text = 'Transfer training parameters';
            app.TransfertrainingparametersCheckBox.Value = true;
            app.TransfertrainedclassifierfileCheckBox = uicheckbox(app.TransferparametersPanel);
            app.TransfertrainedclassifierfileCheckBox.Text = 'Transfer trained classifier file';
            app.TransfertrainedclassifierfileCheckBox.Value = true;
            app.TransferformattedgroundtruthimagesCheckBox = uicheckbox(app.TransferparametersPanel);
            app.TransferformattedgroundtruthimagesCheckBox.Text = 'Transfer formatted groundtruth images';
            app.TransferunformattedtrainingsetROIsCheckBox = uicheckbox(app.TransferparametersPanel);
            app.TransferunformattedtrainingsetROIsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransferunformattedtrainingsetROIsCheckBoxValueChanged, true);
            app.TransferunformattedtrainingsetROIsCheckBox.Tooltip = {'Click this to choose ROIs to import after the proceed button is hit'};
            app.TransferunformattedtrainingsetROIsCheckBox.Text = 'Transfer unformatted trainingset (ROIs)';
            % Show the figure after all components are created
            app.ClassifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierImporterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassifierUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassifierUIFigure)
        end
    end
end