classdef classifyDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassifydataUIFigure          matlab.ui.Figure
        ParallelROIs                  matlab.ui.control.CheckBox
        ChannelListBox                matlab.ui.control.ListBox
        ChannelnamesforselectedtableitemListBoxLabel  matlab.ui.control.Label
        ApplyselectedsettingstoallButton  matlab.ui.control.Button
        DeselectallButton             matlab.ui.control.Button
        SelectallButton               matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        ClassifyselecteddataButton    matlab.ui.control.Button
        SelectpositionsandenterROIsarrayLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SelectclassfiermodelDropDown  matlab.ui.control.DropDown
        SelectclassfiermodelDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        
        function updateTable(app)
            
            app.ChannelListBox.Items={};
            app.ChannelListBox.Enable='off';
            
           % str= app.SelectclassfiermodelDropDown.Value;
              list=app.SelectclassfiermodelDropDown.Items;
            valueClassi=find(matches(list,app.SelectclassfiermodelDropDown.Value));
             
            if numel(valueClassi)==0
                return;
            end 

             classilist= app.Data.varstr;

             classif=classilist{valueClassi};
             classif=evalin('base',classif);

           % classiid=find(matches(app.SelectclassfiermodelDropDown.Items,str));
            
            shallowObj=app.Data.shallowObj;
            t=app.UITable;
            t.ColumnName={'Select','Position','Position Name','Select classification input channel(s)','Select ROIs array','Select frames'};
            t.ColumnEditable=[true false false false true true];
            
            str2={};
            for i=1:numel(shallowObj.fov)
             if numel(shallowObj.fov(i).roi(1).id)
                 tmp= shallowObj.fov(i).roi(1).display.channel;
                 str2=unique([tmp,str2],'stable');
             end
            end


            t.ColumnFormat={[] [] [] [] [] []};
            
            Data={};
            
            for i=1:numel(shallowObj.fov)
                
                strcha='';
                
                if numel(shallowObj.fov(i).roi(1).id)
                    cha=shallowObj.fov(i).roi(1).display.channel;
                  % txt= shallowObj.processing.classification(1).channelName;
                  
                  if ischar(classif.channelName)
                     classif.channelName={classif.channelName};
                  end
                  
                  for j=1:numel(classif.channelName)
                      
                          query= classif.channelName{j};
                          
                   if numel(find(matches(cha,query)))
                      
                       strcha=[strcha classif.channelName{j} ','];
                   else
                       aa=shallowObj.fov(i).roi(1).display.channel;
                       if ischar(aa)
                          strcha=[aa ','];
                       else
                          strcha=[shallowObj.fov(i).roi(1).display.channel{1},','];
                          
                       end
                      
                   end
                  end
                end
               % strcha

               strcha=strcha(1:end-1);
               
                if numel(shallowObj.fov(i).frames)>1
                    pix=find(matches(shallowObj.fov(i).channel,strcha));
                     if numel(pix)
                    fra=shallowObj.fov(i).frames(pix);
                     else
                    fra=shallowObj.fov(i).frames(1);     
                     end
                else
                    fra=shallowObj.fov(i).frames;
                end
                
                Data(i,:)={true num2str(i) shallowObj.fov(i).id strcha ['1:' num2str(numel(shallowObj.fov(i).roi))] ['1:' num2str(fra)]};
            end
            
            t.Data=Data;
        end
        
        
        function gatherVarsFromWorkspace(app)
            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            cc=0;
            cd=0;
            app.Data.st=[];

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});
                
                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};

                    for k=1:numel(tmp.processing.classification)
                        %  k
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];
                    end

                    st.Projectclassi{cc}=tmpclassi;

%                     tmpproj={};
% 
%                     for k=1:numel(tmp.fov)
%                         %  k
%                         tmpproj = [tmpproj tmp.fov(k).id];
%                     end
% 
%                     st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')
                    
                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};
                   % aa= st.Classifier{cd}
                end

            end

          %  st
            app.Data.st=st;
            app.Data.convert={};
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj)
            app.Data.shallowObj=shallowObj;
            
            gatherVarsFromWorkspace(app)

             s=app.Data.st;
            cc=1;
            store=[];
            displaystr={};
            varstr={};
            % list avaialable fovs and classi with ROIs
                for i=1:numel(s.Projectclassi)
                    
                    proj=s.Project{i};
                    
                    shallowObj=evalin('base',proj);

%                     for k=1:numel(s.Projectpos{i})
%                         
%                         tmp= evalin('base',[proj '.fov(' num2str(k) ')']);
%                         
%                         if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
%                         varstr{cc}=[proj '.fov(' num2str(k) ')'];
%                         displaystr{cc}=[proj '  //  ' s.Projectpos{i}{k}];
%                         if numel(store)==0
%                         store=numel(tmp.roi);
%                         end
%                         cc=cc+1;
%                         end
%                     end
                    for k=1:numel(s.Projectclassi{i})
                        
                        tmp= evalin('base',[proj '.processing.classification(' num2str(k) ')']);
                        
                        if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                        varstr{cc}=[proj '.processing.classification(' num2str(k) ')'];
                        displaystr{cc}=[proj '  //  ' s.Projectclassi{i}{k}];
                        if numel(store)==0
                        store=numel(tmp.roi);
                        end
                        cc=cc+1;
                        end
                    end
                end
                for i=1:numel(s.Classifier)
                    
                    clas=s.Classifier{i};
                    
                    tmp= evalin('base',clas);
                        
                    if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                        varstr{cc}=clas;
                        
                        displaystr{cc}=[clas ' - ' num2str(numel(tmp.roi)) ' ROIs available'];
                        
                        if numel(store)==0
                           store=numel(tmp.roi);
                        end
                       
                        cc=cc+1;
                     end
                        
                end
                
                app.SelectclassfiermodelDropDown.Items=displaystr';
                app.Data.varstr=varstr; % list of classifier variables in the workspace
                app.Data.displaystr=displaystr;


%             str={};
%             for i=1:numel(shallowObj.processing.classification)
%                 str{i}=shallowObj.processing.classification(i).strid;
%             end
           % app.SelectclassfiermodelDropDown.Items=str;
            
            updateTable(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: ClassifydataUIFigure
        function ClassifydataUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SelectclassfiermodelDropDown
        function SelectclassfiermodelDropDownValueChanged(app, event)
            value = app.SelectclassfiermodelDropDown.Value;
            
            shallowObj=app.Data.shallowObj;
            
          %  pix=find(contains(app.SelectclassfiermodelDropDown.Items,value));
            
            updateTable(app)
            
%             dat=app.UITable.Data;
%             
%             dat{:,4}=num2str(shallowObj.processing.classification(pix).channel);
%             
%             app.UITable.Data=dat;
        end

        % Button pushed function: ClassifyselecteddataButton
        function ClassifyselecteddataButtonPushed(app, event)
             shallowObj=app.Data.shallowObj;
            
             list=app.SelectclassfiermodelDropDown.Items;
             valueClassi=find(matches(list,app.SelectclassfiermodelDropDown.Value));
             
             classilist= app.Data.varstr;

             classif=classilist{valueClassi};
             classif=evalin('base',classif);
             

             data=app.UITable.Data;
             
             selpos=find(cellfun(@(x) x==1,data(:,1)));


             frames={};
             channel={};
             
             roiobj=[];
             cc=1;
             for i=1:numel(selpos)
                
                 tmp=str2num(data{selpos(i),5});
                 
                 if sum(ismember(tmp,1:numel(shallowObj.fov(i).roi)))==numel(tmp)
                     roiobj=[roiobj shallowObj.fov(selpos(i)).roi(tmp)];
                     
                     txt=textscan(data{selpos(i),4},'%s','Delimiter',',');

                    str={};
                    
                    for j=1:numel(txt{1})
                        str{j}=txt{1}{j};
                    end
                    
                     
                     %pix=find(matches(shallowObj.fov(selpos(i)).roi(1).display.channel,data{selpos(i),4}))
                     for j=1:numel(tmp)
                   
                          channel{cc}=str; %shallowObj.fov(selpos(i)).roi(1).display.channel(pix);
                          frames{cc}=str2num(data{selpos(i),6});
                         
                         cc=cc+1;
                     end
                 end
             end

           %  channel
          
    %     aa=channel{1}{1}
            % roiscell
             d = uiprogressdlg(app.ClassifydataUIFigure,'Title','Please Wait...',...
                    'Message','Starting data classification...');
            d.Value=0.01;
            
      
            arg={'Progress',d,'Channel',channel,'Frames',frames};
            
            if app.ParallelROIs.Value==true
                arg=[arg {'Parallel'}];
            end
 
             classifyData(classif,roiobj,arg{:});

                uialert(app.ClassifydataUIFigure,'Classification is complete!','Success','Icon','success');
             
             %shallowSave(shallowObj);
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            data=app.UITable.Data;
             
            data(:,1)={true};
             
             app.UITable.Data=data;
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
             data=app.UITable.Data;
             
            data(:,1)={false};
             
             app.UITable.Data=data;
        end

        % Button pushed function: ApplyselectedsettingstoallButton
        function ApplyselectedsettingstoallButtonPushed(app, event)
             data=app.UITable.Data;
             
             selectedfovs=find(cellfun(@(x) x==1,data(:,1)));
             
             if numel(selectedfovs)==0
                 uialert(app.ClassifydataUIFigure,'No position was selected','Error');
             end
             
             for i=1:size(data,1)
                 
                 if i~=selectedfovs(1)
                     
                     data(i,4:end)=data(selectedfovs(1),4:end);
                     
                 end
             end
             
             app.UITable.Data=data;
        end

        % Value changed function: ChannelListBox
        function ChannelListBoxValueChanged(app, event)
            
            value = app.ChannelListBox.Value;
            
            selcel=app.UITable.UserData; % selected cell
            
            cha='';
            for i=1:numel(value)
                cha=[cha value{i} ', '];
            end
            
            cha=cha(1:end-2);
            
            if numel(selcel)
            
                app.UITable.Data{selcel(1),4} = cha; 
                
            end
            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            if numel(indices)==0
                return
            end
            
            Data=app.UITable.Data; 
            shallowObj=app.Data.shallowObj;
            
            fovid=indices(1);
            
            
            cha=shallowObj.fov(fovid).roi(1).display.channel;
            if ischar(cha) % this field is probably empty
                app.ChannelListBox.Items={}; 
                app.ChannelListBox.Enable='off';
                return
            end
            app.ChannelListBox.Items=cha; 
            app.ChannelListBox.Enable='on';
            
            selcha=Data{fovid,4};
            f=textscan(selcha,'%s','Delimiter',',');
            
            val={};
            for i=1:numel(f)
                if numel(find(matches(cha,f{i})))
                    val=[val f{i}];
                end
            end
            app.ChannelListBox.Value=val;
            
            app.UITable.UserData=indices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassifydataUIFigure = uifigure('Visible', 'off');
            app.ClassifydataUIFigure.Name = 'Classify data';
            app.ClassifydataUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassifydataUIFigureCloseRequest, true);
            app.SelectclassfiermodelDropDownLabel = uilabel(app.ClassifydataUIFigure);
            app.SelectclassfiermodelDropDownLabel.Text = 'Select classfier/model: ';
            app.SelectclassfiermodelDropDown = uidropdown(app.ClassifydataUIFigure);
            app.SelectclassfiermodelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectclassfiermodelDropDownValueChanged, true);
            app.UITable = uitable(app.ClassifydataUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectpositionsandenterROIsarrayLabel = uilabel(app.ClassifydataUIFigure);
            app.SelectpositionsandenterROIsarrayLabel.Text = 'Select positions and enter ROIs array:';
            app.ClassifyselecteddataButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.ClassifyselecteddataButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyselecteddataButtonPushed, true);
            app.ClassifyselecteddataButton.Text = 'Classify selected data';
            app.CloseButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.ApplyselectedsettingstoallButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.ApplyselectedsettingstoallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyselectedsettingstoallButtonPushed, true);
            app.ApplyselectedsettingstoallButton.Text = 'Apply selected settings to all';
            app.ChannelnamesforselectedtableitemListBoxLabel = uilabel(app.ClassifydataUIFigure);
            app.ChannelnamesforselectedtableitemListBoxLabel.Text = 'Channel names for selected table item:';
            app.ChannelListBox = uilistbox(app.ClassifydataUIFigure);
            app.ChannelListBox.Multiselect = 'on';
            app.ChannelListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListBoxValueChanged, true);
            app.ChannelListBox.Value = {'Item 1'};
            app.ParallelROIs = uicheckbox(app.ClassifydataUIFigure);
            app.ParallelROIs.Enable = 'off';
            app.ParallelROIs.Text = 'Parallel computing on ROIs';
            % Show the figure after all components are created
            app.ClassifydataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifyDataGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassifydataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassifydataUIFigure)
        end
    end
end