classdef detecdiv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DetecDivUIFigure               matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewprojectMenu                 matlab.ui.container.Menu
        OpenprojectMenu                matlab.ui.container.Menu
        OpenrecentMenu                 matlab.ui.container.Menu
        Item1Menu                      matlab.ui.container.Menu
        SaveselectedprojectMenu        matlab.ui.container.Menu
        Closeproject                   matlab.ui.container.Menu
        ExportprojecttoPhylocellMenu   matlab.ui.container.Menu
        NewprojectindependentclassiiferMenu  matlab.ui.container.Menu
        OpenprojectindependentclassifierMenu  matlab.ui.container.Menu
        OpenrecentClassiMenu           matlab.ui.container.Menu
        SaveprojectindependentclassifierMenu  matlab.ui.container.Menu
        CloseprojectindependentclassifierMenu  matlab.ui.container.Menu
        NewpipelineMenu                matlab.ui.container.Menu
        OpenpipelineMenu               matlab.ui.container.Menu
        SavepipelineMenu               matlab.ui.container.Menu
        ClosepipelineMenu              matlab.ui.container.Menu
        ClassifierRepositoryMenu       matlab.ui.container.Menu
        ConvertoldtrainingandresultdataMenu  matlab.ui.container.Menu
        FunctionsMenu                  matlab.ui.container.Menu
        ClassifyDataMenu               matlab.ui.container.Menu
        ProcessdataMenu                matlab.ui.container.Menu
        ExportdataMenu                 matlab.ui.container.Menu
        MakeROImoviesMenu              matlab.ui.container.Menu
        ProjectMenu                    matlab.ui.container.Menu
        PositionsMenu                  matlab.ui.container.Menu
        AddPositionsDataMenu           matlab.ui.container.Menu
        SetFrameOrientationMenu        matlab.ui.container.Menu
        CreateFullFrameROisMenu        matlab.ui.container.Menu
        DeleteROIsMenu                 matlab.ui.container.Menu
        AdjustROIsMenu                 matlab.ui.container.Menu
        DeletePositionsMenu_2          matlab.ui.container.Menu
        RestoredeletedROIsMenu         matlab.ui.container.Menu
        ClassifiersMenu                matlab.ui.container.Menu
        AddclassifierMenu              matlab.ui.container.Menu
        DeleteclassifierMenu           matlab.ui.container.Menu
        AdjustROIsMenu_3               matlab.ui.container.Menu
        DeleteROIsMenu_2               matlab.ui.container.Menu
        RestoredeletedROIsMenu_2       matlab.ui.container.Menu
        AdjustROIsMenu_2               matlab.ui.container.Menu
        DisplayMenu                    matlab.ui.container.Menu
        RefreshtreewindowMenu          matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        UserpreferencesMenu            matlab.ui.container.Menu
        ProjectsPanel                  matlab.ui.container.Panel
        OpenButton                     matlab.ui.control.Button
        ProcessdataButton              matlab.ui.control.Button
        AddprocessorButton             matlab.ui.control.Button
        ClassifydataButton             matlab.ui.control.Button
        UpdaterawdatapathButton        matlab.ui.control.Button
        ExtractROIhypervolumesButton   matlab.ui.control.Button
        IdentifyROIsinpositionsButton  matlab.ui.control.Button
        ProjectInformationLabel        matlab.ui.control.Label
        AddclassifierButton            matlab.ui.control.Button
        AdddataButton                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Tree                           matlab.ui.container.Tree
        ProjectsNode                   matlab.ui.container.TreeNode
        IndependentClassifiersNode     matlab.ui.container.TreeNode
        IndependentPipelinesNode       matlab.ui.container.TreeNode
    end


    properties (Access = private)
        Data % Description
        RecentProjects string = strings(0)  % liste des chemins absolus récents
        RecentProjectsFile string          % chemin complet du .mat persistant
        RecentClassifiers string = strings(0)    % chemins complets vers *_classification.mat
        RecentClassifiersFile string             % stockage persistant

    end

    methods (Access = public)

        function displayNodes(app)
            cc=1;
            %h1=[];
            %hproj=[];
            %hclassi=[];

            t=app.ProjectsNode.Children;
            t.delete;
            t=app.IndependentClassifiersNode.Children;
            t.delete;
            t=app.IndependentPipelinesNode.Children;
            t.delete;

            [pth fle ext]= fileparts(which('detecdiv.mlapp'));

            for i=1:numel(app.Data.Project)
                h1(i)=uitreenode(app.ProjectsNode,'Text',app.Data.Project{i},'Tag','Project','UserData',i,'Icon',fullfile(pth,'detecDiv_logo.png'));


                for k=1:numel(app.Data.Projectclassi{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open classifier...');
                    m.MenuSelectedFcn={@contextMenuClassiFcn,[i,k],'Projectclassi'};
                    m = uimenu(cm,'Text','Delete classifier');
                    m.MenuSelectedFcn={@contextMenuDeleteClassiFcn,[i,k],'Projectclassi'};
                    m = uimenu(cm,'Text','Delete ROIs...');
                    m.MenuSelectedFcn={@contextMenuDeleteROIsFcn,[i,k],'Projectclassi'};

                    g1(i,k)=uitreenode(h1(i),'Text',app.Data.Projectclassi{i}{k},'Tag','Projectclassi','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'brain.png'));


                    %                  if numel(app.Data.Projectclassirois{i})
                    %                    for n=1:numel(app.Data.Projectclassirois{i}{k})
                    %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                    %                       cm=uicontextmenu(app.DetecDivUIFigure);
                    %                     m = uimenu(cm,'Text','Open ROI...');
                    %                     m.MenuSelectedFcn={@contextMenuROIFcn,[i,k,n],'Projectclassirois'};
                    %                     ''ContextMenu',cm'
                    %                   uitreenode(g1(i,k),'Text',app.Data.Projectclassirois{i}{k}{n},'Tag','Projectclassirois','UserData',[i,k,n],'Icon',fullfile(pth,'roi.png'));
                    %                                     % disabled because too heavy with large projects
                    %                   end
                    %                 end


                end

                for k=1:numel(app.Data.Projectprocess{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open processor...');
                    m.MenuSelectedFcn={@contextMenuProcessFcn,[i,k],'Projectprocess'};
                    m = uimenu(cm,'Text','Duplicate processor...');
                    m.MenuSelectedFcn = {@contextMenuDuplicateProcessFcn,[i,k],'Projectprocess'};
                    m = uimenu(cm,'Text','Delete processor');
                    m.MenuSelectedFcn={@contextMenuDeleteProcessFcn,[i,k],'Projectprocess'};
                    g1(i,k)=uitreenode(h1(i),'Text',app.Data.Projectprocess{i}{k},'Tag','Projectprocess','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'processor.png'));


                    %                  if numel(app.Data.Projectclassirois{i})
                    %                    for n=1:numel(app.Data.Projectclassirois{i}{k})
                    %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                    %                       cm=uicontextmenu(app.DetecDivUIFigure);
                    %                     m = uimenu(cm,'Text','Open ROI...');
                    %                     m.MenuSelectedFcn={@contextMenuROIFcn,[i,k,n],'Projectclassirois'};
                    %                     ''ContextMenu',cm'
                    %                   uitreenode(g1(i,k),'Text',app.Data.Projectclassirois{i}{k}{n},'Tag','Projectclassirois','UserData',[i,k,n],'Icon',fullfile(pth,'roi.png'));
                    %                                     % disabled because too heavy with large projects
                    %                   end
                    %                 end


                end


                for k=1:numel(app.Data.Projectpipeline{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open pipeline run...');
                    m.MenuSelectedFcn={@contextMenuPipelineRunOpenFcn,[i,k],'Projectpipeline'};
                    m = uimenu(cm,'Text','Delete pipeline run');
                    m.MenuSelectedFcn={@contextMenuPipelineRunDeleteFcn,[i,k],'Projectpipeline'};
                    g1(i,k)=uitreenode(h1(i),'Text',app.Data.Projectpipeline{i}{k},'Tag','Projectpipeline','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'pipeline_run.png'));
                end


                for k=1:numel(app.Data.Projectpos{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open position...');
                    m.MenuSelectedFcn={@contextMenuPositionFcn,[i,k],'Projectpos'};
                    m = uimenu(cm,'Text','Delete ROIs...');
                    m.MenuSelectedFcn={@contextMenuDeleteROIsFcn,[i,k],'Projectpos'};
                    m = uimenu(cm,'Text','Delete current position');
                    m.MenuSelectedFcn={@contextMenuDeletePositionFcn,[i,k],'Projectpos'};

                    g2(i,k)=uitreenode(h1(i),'Text',app.Data.Projectpos{i}{k},'Tag','Projectpos','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'data.png'));

                    %                      if numel(app.Data.Projectposrois{i})
                    %
                    %                     for n=1:numel(app.Data.Projectposrois{i}{k})
                    %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                    %                    %   cm=uicontextmenu(app.DetecDivUIFigure);
                    %                   %  m = uimenu(cm,'Text','Open ROI...');
                    %                   %  m.MenuSelectedFcn={@contextMenuROIFcn,[i,k,n],'Projectposrois'};
                    %
                    %                 %        uitreenode(g2(i,k),'Text',app.Data.Projectposrois{i}{k}{n},'Tag','Projectposrois','UserData',[i,k,n],'Icon',fullfile(pth,'roi.png'));
                    %                       % disabled because too heavy with large projects
                    %                     end
                    %                      end

                end
            end

            for i=1:numel(app.Data.Classifier)
                cm=uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cm,'Text','Open classifier...');
                m.MenuSelectedFcn={@contextMenuClassiFcn,i,'Classifier'};
                m = uimenu(cm,'Text','Close classifier');
                m.MenuSelectedFcn={@contextMenuDeleteClassiFcn,i,'Classifier'};
                m = uimenu(cm,'Text','Delete ROIs...');
                m.MenuSelectedFcn={@contextMenuDeleteROIsFcn,i,'Classifier'};

                g3(i)=uitreenode(app.IndependentClassifiersNode,'Text',app.Data.Classifier{i},'Tag','Classifier','UserData',i,'ContextMenu',cm,'Icon',fullfile(pth,'brain.png'));

                %                if numel(app.Data.Classifierrois{i})

                %                    for n=1:numel(app.Data.Classifierrois{i})
                %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                %                       cm=uicontextmenu(app.DetecDivUIFigure);
                %                     m = uimenu(cm,'Text','Open ROI...');
                %                     m.MenuSelectedFcn={@contextMenuROIFcn,[i,n],'Projectposrois'};
                %                     'ContextMenu',cm
                %          uitreenode(g3(i),'Text',app.Data.Projectclassirois{i}{n},'Tag','Classifierrois','UserData',[i,n],'Icon',fullfile(pth,'roi.png'));
                %                                       % disabled because too heavy with large projects
                %                     end
                %                end


            end

            for i=1:numel(app.Data.Pipeline)
                cm=uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cm,'Text','Open pipeline...');
                m.MenuSelectedFcn={@contextMenuPipelineOpenFcn,i,'Pipeline'};
                m = uimenu(cm,'Text','Save pipeline');
                m.MenuSelectedFcn={@contextMenuPipelineSaveFcn,i,'Pipeline'};
                m = uimenu(cm,'Text','Close pipeline');
                m.MenuSelectedFcn={@contextMenuPipelineCloseFcn,i,'Pipeline'};
                m = uimenu(cm,'Text','Delete pipeline');
                m.MenuSelectedFcn={@contextMenuPipelineDeleteFcn,i,'Pipeline'};

                g4(i)=uitreenode(app.IndependentPipelinesNode,'Text',app.Data.Pipeline{i},'Tag','Pipeline','UserData',i,'ContextMenu',cm,'Icon',fullfile(pth,'pipeline.png'));
            end

            expand(app.ProjectsNode);
            expand(app.IndependentClassifiersNode);
            expand(app.IndependentPipelinesNode);

            function contextMenuDeleteProcessFcn(src,event,arg,str)

                %               %  str,arg
                cc=arg(1);
                %                 if strcmp(str,'Processor')
                %                      cc=arg(1);
                %                      clas=app.Data.Classifier{cc};
                %                      %clas=evalin('base',clas);
                %
                %                      evalin('base',['clear ' clas]);
                %
                %                 end

                if strcmp(str,'Projectprocess')

                    proj=app.Data.Project{cc(1)};
                    pos=arg(2);
                    shallowObj=evalin('base',proj);
                    clas=shallowObj.processing.processor(pos);
                    n=1:numel(shallowObj.processing.processor);
                    pix=setxor(n,pos);

                    aa=uiconfirm(app.DetecDivUIFigure,'Are you really sure you want to delete this processor?','Warning');
                    if strcmp(aa,'OK')
                        % if numel(pix)

                        rmdir(clas.path,'s')
                        shallowObj.processing.processor=shallowObj.processing.processor(pix);
                        %   else

                        %   end
                    else
                        return;
                    end
                end

                % classifierGUI(clas)
                %    i,k
                RefreshtreewindowMenuSelected(app)
            end


function contextMenuDuplicateProcessFcn(src,event,arg,str) %#ok<INUSD>
    if ~strcmp(str,'Projectprocess')
        return;
    end

    proj = app.Data.Project{arg(1)};
    pos  = arg(2);
    shallowObj = evalin('base', proj);

    % ---- purge invalid processors ----
    procList = shallowObj.processing.processor;
    if ~isempty(procList)
        valid = arrayfun(@(p) isvalid(p), procList);
        if any(~valid)
            procList = procList(valid);
            shallowObj.processing.processor = procList;
        end
    end

    if isempty(procList) || pos > numel(procList)
        uialert(app.DetecDivUIFigure, ...
            'This processor no longer exists (invalid handle). Tree will refresh.', ...
            'Warning','Icon','warning');
        RefreshtreewindowMenuSelected(app);
        return;
    end

    srcProc = procList(pos);

    % ---- propose a default name ----
    defaultName = [srcProc.strid '_copy'];
    answer = inputdlg({'New processor name:'}, 'Duplicate processor', 1, {defaultName});
    if isempty(answer), return; end
    newName = strtrim(answer{1});
    if isempty(newName), return; end

    % ---- avoid duplicate names ----
    existing = arrayfun(@(p) p.strid, procList, 'UniformOutput', false);
    if any(strcmp(existing, newName))
        uialert(app.DetecDivUIFigure, ...
            ['A processor named "' newName '" already exists in this project.'], ...
            'Name conflict','Icon','warning');
        return;
    end

    % ---- create new processor in project ----
    shallowObj.addProcessor('name', newName);
    dstProc = shallowObj.processing.processor(end);

    % ---- copy settings ----
    try, dstProc.processFun  = srcProc.processFun;  end
    try, dstProc.processArg  = srcProc.processArg;  end
    try, dstProc.runProfiles = srcProc.runProfiles; end
    try, dstProc.description = srcProc.description; end
    try, dstProc.category    = srcProc.category;    end

    try
        processSave(dstProc);
    catch ME
        warning('Duplicate processor: save failed: %s', ME.message);
    end

    RefreshtreewindowMenuSelected(app);
end


function contextMenuPipelineRunOpenFcn(src,event,arg,str) %#ok<INUSD>
    if ~strcmp(str,'Projectpipeline')
        return;
    end

    proj = app.Data.Project{arg(1)};
    pos  = arg(2);
    shallowObj = evalin('base', proj);

    if ~isfield(shallowObj.processing,'pipeline') || pos > numel(shallowObj.processing.pipeline)
        uialert(app.DetecDivUIFigure, 'Pipeline run not found.', 'Warning', 'Icon','warning');
        return;
    end

    runObj = shallowObj.processing.pipeline(pos);
    jsonFile = fullfile(runObj.path, 'run.json');
    if exist(jsonFile,'file')
        edit(jsonFile);
    else
        uialert(app.DetecDivUIFigure, sprintf('run.json not found:\\n%s', jsonFile), 'Missing file', 'Icon','warning');
    end
end

function contextMenuPipelineRunDeleteFcn(src,event,arg,str) %#ok<INUSD>
    if ~strcmp(str,'Projectpipeline')
        return;
    end

    proj = app.Data.Project{arg(1)};
    pos  = arg(2);
    shallowObj = evalin('base', proj);

    aa = uiconfirm(app.DetecDivUIFigure, 'Are you really sure you want to delete this pipeline run?', 'Warning');
    if ~strcmp(aa,'OK')
        return;
    end

    if ~isfield(shallowObj.processing,'pipeline') || pos > numel(shallowObj.processing.pipeline)
        RefreshtreewindowMenuSelected(app);
        return;
    end

    runObj = shallowObj.processing.pipeline(pos);
    n=1:numel(shallowObj.processing.pipeline);
    pix=setxor(n,pos);

    if ~isempty(runObj.path) && exist(runObj.path,'dir')
        try
            rmdir(runObj.path,'s');
        catch ME
            warning('Could not delete pipeline run folder: %s', ME.message);
        end
    end

    shallowObj.processing.pipeline = shallowObj.processing.pipeline(pix);
    RefreshtreewindowMenuSelected(app);
end

function contextMenuPipelineOpenFcn(src,event,arg,str) %#ok<INUSD>
    if ~strcmp(str,'Pipeline')
        return;
    end

    varName = app.Data.Pipeline{arg(1)};
    pipeObj = evalin('base', varName);
    jsonFile = fullfile(pipeObj.path, 'pipeline.json');
    if exist(jsonFile,'file')
        edit(jsonFile);
    else
        uialert(app.DetecDivUIFigure, sprintf('pipeline.json not found:\\n%s', jsonFile), 'Missing file', 'Icon','warning');
    end
end

function contextMenuPipelineSaveFcn(src,event,arg,str) %#ok<INUSD>
    if ~strcmp(str,'Pipeline')
        return;
    end

    varName = app.Data.Pipeline{arg(1)};
    pipeObj = evalin('base', varName);
    try
        pipelineSave(pipeObj);
    catch ME
        uialert(app.DetecDivUIFigure, ['Save failed: ' ME.message], 'Error', 'Icon','error');
    end
end

function contextMenuPipelineCloseFcn(src,event,arg,str) %#ok<INUSD>
    if ~strcmp(str,'Pipeline')
        return;
    end

    varName = app.Data.Pipeline{arg(1)};
    evalin('base', ['clear ' varName]);
    RefreshtreewindowMenuSelected(app);
end

function contextMenuPipelineDeleteFcn(src,event,arg,str) %#ok<INUSD>
    if ~strcmp(str,'Pipeline')
        return;
    end

    varName = app.Data.Pipeline{arg(1)};
    pipeObj = evalin('base', varName);

    aa = uiconfirm(app.DetecDivUIFigure, 'Are you really sure you want to delete this pipeline?', 'Warning');
    if ~strcmp(aa,'OK')
        return;
    end

    if ~isempty(pipeObj.path) && exist(pipeObj.path,'dir')
        try
            rmdir(pipeObj.path,'s');
        catch ME
            warning('Could not delete pipeline folder: %s', ME.message);
        end
    end

    evalin('base', ['clear ' varName]);
    RefreshtreewindowMenuSelected(app);
end

            function contextMenuDeleteClassiFcn(src,event,arg,str)

               cc = arg(1);

    % ---------------------------------------------------------------------
    % Cas 1 : classifier indépendant dans la base
    % ---------------------------------------------------------------------
    if strcmp(str,'Classifier')
        clas = app.Data.Classifier{cc};
        evalin('base',['clear ' clas]);
    end

    % ---------------------------------------------------------------------
    % Cas 2 : classifier attaché à un projet (shallow)
    % ---------------------------------------------------------------------
    if strcmp(str,'Projectclassi')
        proj = app.Data.Project{cc(1)};
        pos  = arg(2);

        shallowObj = evalin('base',proj);
        clas       = shallowObj.processing.classification(pos);

        n   = 1:numel(shallowObj.processing.classification);
        pix = setxor(n,pos);

        % confirmation utilisateur
        aa = uiconfirm(app.DetecDivUIFigure, ...
            'Are you really sure you want to delete the classifier?', ...
            'Warning');

        if ~strcmp(aa,'OK')
            return;
        end

        % -----------------------------------------------------------------
        % 🔥 Suppression sécurisée du dossier associé à ce classifier
        % -----------------------------------------------------------------
        classiPath = clas.path;

        if ~isempty(classiPath) && ischar(classiPath) && exist(classiPath,'dir')
            try
                rmdir(classiPath, 's');
            catch ME
                warning(['Could not delete classifier folder at: ' classiPath ...
                         '  | Reason: ' ME.message]);
            end
        else
            % Optionnel : avertir si le dossier n’existe pas
            disp(['[Info] Classifier folder does not exist or is invalid: ' char(classiPath)]);
        end

        % Mise à jour du shallowObj
        shallowObj.processing.classification = ...
            shallowObj.processing.classification(pix);
    end

    % Rafraîchissement de l’arbre
    RefreshtreewindowMenuSelected(app)

            end

            function contextMenuClassiFcn(src,event,arg,str)

                %  str,arg
                cc=arg(1);
                if strcmp(str,'Classifier')
                    cc=arg(1);
                    clas=app.Data.Classifier{cc};
                    clas=evalin('base',clas);
                end
                if strcmp(str,'Projectclassi')
                    proj=app.Data.Project{cc(1)};
                    pos=arg(2);
                    shallowObj=evalin('base',proj);
                    clas=shallowObj.processing.classification(pos);
                end

                classifierGUI(clas)
                %    i,k
            end
            function contextMenuProcessFcn(src,event,arg,str)

    cc = arg(1);

    if strcmp(str,'Projectprocess')
        proj = app.Data.Project{cc(1)};
        pos  = arg(2);
        shallowObj = evalin('base', proj);
        proc = shallowObj.processing.processor(pos);

        processDataGUI(shallowObj, proc);
    end

end


            function contextMenuPositionFcn(src,event,arg,str)

                cc=arg(1);
                pos=arg(2);

                proj=app.Data.Project{cc(1)};
                shallowObj=evalin('base',proj);

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Loading raw data from source files; Please wait...');
                d.Value=0.33;

                if numel(shallowObj.fov(pos).srcpath{1})>0
                    im=readImage(shallowObj.fov(pos),1,1);

                    if numel(im)==0 & numel(shallowObj.fov(pos).srcpath{1})>0
                        errordlg('The path to your raw data is incorrect; please update the raw data path !');
                    end
                end


                shallowObj.fov(pos).view(shallowObj.fov(pos).display.frame,[],shallowObj);


                d.Value=0.9;
                d.Message='Data loaded...';
                pause(1);
                close(d);

            end
            function contextMenuDeleteROIsFcn(src,event,arg,str)


                cc=arg(1);

                if strcmp(str,'Classifier')
                    clas=app.Data.Classifier{cc};
                    clas=evalin('base',clas);
                end
                if strcmp(str,'Projectclassi')
                    proj=app.Data.Project{cc(1)};
                    pos=arg(2);
                    shallowObj=evalin('base',proj);
                    clas=shallowObj.processing.classification(pos);
                end
                if strcmp(str,'Projectpos')
                    proj=app.Data.Project{cc(1)};
                    pos=arg(2);
                    shallowObj=evalin('base',proj);
                    clas=shallowObj.fov(pos);
                end


                defroi=['1:'  num2str(numel(clas.roi)) ];
                prompt = {'ROIs that will be deleted:'};%,'Period between frames for each channel (in frames units):'};
                dlgtitle = 'Deleting ROIs';

                dims = [1 100];


                definput = {defroi};%, num2str(inte)};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                if numel(answer)==0
                    return;
                end

                selection=uiconfirm(app.DetecDivUIFigure,'This will erase selected ROIs; Proceed?','Warning');


                if strcmp(selection,'OK')

                    id=str2num(answer{1});
                    if numel(id)
                        pix=setxor(1:numel(clas.roi),id);

                        if numel(pix)==0
                            clas.roi=roi;
                            if ~strcmp(str,'Projectpos')
                                clas.channelName={};
                            end
                        else
                            clas.roi=clas.roi(pix);
                        end

                    end

                    RefreshtreewindowMenuSelected(app)
                end
            end

            function contextMenuDeletePositionFcn(src,event,arg,str)

                cc=arg(1);
                pos=arg(2);

                proj=app.Data.Project{cc(1)};
                shallowObj=evalin('base',proj);

                selection=uiconfirm(app.DetecDivUIFigure,'This will erase the selected position; Proceed?','Warning');

                if strcmp(selection,'OK')

                    pix=setxor(1:numel(shallowObj.fov),pos);
                    shallowObj.fov=shallowObj.fov(pix);
                    RefreshtreewindowMenuSelected(app)
                end

            end


            %             function contextMenuROIFcn(src,event,arg,str)
            %
            %                %  str,arg
            %
            %
            %                 if strcmp(str,'Projectclassirois')
            %                     cc=arg(1);
            %                 pos=arg(2);
            %                 ro=arg(3);
            %
            %
            %                 proj=app.Data.Project{cc};
            %                 shallowObj=evalin('base',proj);
            %                 roiObj=shallowObj.processing.classification(pos).roi(ro);
            %                 roiObj.view;
            %                 end
            %
            %                 if strcmp(str,'Projectposrois')
            %                  cc=arg(1);
            %                 pos=arg(2);
            %                 ro=arg(3);
            %
            %
            %                 proj=app.Data.Project{cc};
            %                 shallowObj=evalin('base',proj);
            %                 roiObj=shallowObj.fov(pos).roi(ro);
            %                 roiObj.view;
            %                 end
            %
            %                 if strcmp(str,'Classifierrois')
            %                  cc=arg(1);
            %                  ro=arg(2);
            %                  clas=app.Data.Classifier{cc};
            %                  clas=evalin('base',clas);
            %
            %                 roiObj=clas.roi(ro);
            %                 roiObj.view;
            %                   end
            %
            %
            %             end


        end


        function gatherVarsFromWorkspace(app)
            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Pipeline',{''},'Projectpos',{''},'Projectclassi',{''},'Projectprocess',{''},'Projectpipeline',{''},'Projectposrois',{''},'Projectclassirois',{''},'Classifierrois',{''});
            cc=0;
            cd=0;
            cp=0;
            app.Data={};

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});


                if isa(tmp,'shallow')
                    %  disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};

                    for k=1:numel(tmp.processing.classification)
                        %  k
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];

                        if numel(tmp.processing.classification(k).roi)==1 && numel(tmp.processing.classification(k).roi.id)==0
                            ntot=0;
                        else
                            ntot=numel(tmp.processing.classification(k).roi);
                        end

                        tmproi={};

                        %  ntot

                        for n=1:ntot

                            tmproi=[tmproi [num2str(n) ' - ' tmp.processing.classification(k).roi(n).id]];

                        end

                        % tmproi
                        st.Projectclassirois{cc}{k}=tmproi;


                    end

                    st.Projectclassi{cc}=tmpclassi;
                    tmpprocess={};

                    if isfield(tmp.processing,'processor')
                        for k=1:numel(tmp.processing.processor)
                            %  k
                            procList = tmp.processing.processor;
if ~isempty(procList)
    valid = arrayfun(@(p) isvalid(p), procList);
    if any(~valid)
        procList = procList(valid);
        tmp.processing.processor = procList;
    end
end

tmpprocess = {};
for k = 1:numel(procList)
    tmpprocess{end+1} = procList(k).strid; %#ok<AGROW>
end


                        end

                        st.Projectprocess{cc}=tmpprocess;
                    end

                    tmppipe = {};

                    if isfield(tmp.processing,'pipeline')
                        pipeList = tmp.processing.pipeline;
                        if ~isempty(pipeList)
                            valid = true(size(pipeList));
                            for kk = 1:numel(pipeList)
                                try
                                    ptmp = pipeList(kk);
                                    valid(kk) = isobject(ptmp) && (isprop(ptmp,'runId') || isprop(ptmp,'strid'));
                                catch
                                    valid(kk) = false;
                                end
                            end
                            if any(~valid)
                                pipeList = pipeList(valid);
                                tmp.processing.pipeline = pipeList;
                            end
                        end

                        for k = 1:numel(pipeList)
                            if isprop(pipeList(k),'runId') && ~isempty(pipeList(k).runId)
                                tmppipe{end+1} = pipeList(k).runId; %#ok<AGROW>
                            else
                                tmppipe{end+1} = pipeList(k).strid; %#ok<AGROW>
                            end
                        end
                    end

                    st.Projectpipeline{cc}=tmppipe;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        if numel(tmp.fov(k).srcpath{1})>0
                            tmpproj = [tmpproj [num2str(k) ' - ' tmp.fov(k).id]];
                            %  aa=tmp.fov(k).srcpath
                        end

                        if numel(tmp.fov(k).roi)==1 && numel(tmp.fov(k).roi.id)==0
                            ntot=0;
                        else
                            ntot=numel(tmp.fov(k).roi);
                        end

                        tmproi={};

                        %  ntot

                        for n=1:ntot

                            tmproi=[tmproi [num2str(n) ' - ' tmp.fov(k).roi(n).id]];

                        end

                        % tmproi
                        st.Projectposrois{cc}{k}=tmproi;


                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')

                    %     disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};

                    if numel(tmp.roi)==1 && numel(tmp.roi.id)==0
                        ntot=0;
                    else
                        ntot=numel(tmp.roi);
                    end

                    tmproi={};

                    %  ntot

                    for n=1:ntot

                        tmproi=[tmproi [num2str(n) ' - ' tmp.roi(n).id]];

                    end

                    % tmproi,cc
                    st.Classifierrois{cd}=tmproi;

                end

                if isa(tmp,'pipeline')
                    cp = cp + 1;
                    st.Pipeline{cp} = varlist{i};
                end

            end

            %  st
            app.Data=st;

            %  st
        end


        function check = checkImagePath(app,proj)

            check=ones(1,numel(proj.fov));

            d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                'Message','Checking raw data path in the project...');
            d.Value=0.0;
            for i=1:numel(proj.fov)
                d.Value=i./numel(proj.fov);
                if numel(proj.fov(i).srcpath{1})>0
                    im=readImage(proj.fov(i),1,1);

                    if numel(im)==0 & numel(proj.fov(1).srcpath{1})>0
                        check(i)=0;
                    end
                end
            end


            if numel(find(check==0))
                d.Message='Some raw data paths are invalid. It will be impossible to display the raw images!';
                pause(5);
            else
                d.Message='The paths are OK!';
                pause(1);
            end

            close(d);
        end
    end

    methods (Access = private)

        function  displayClassiImage(app,clas)

            selectedNode=app.Tree.SelectedNodes;


            if numel(selectedNode.NodeData)==0
                loade=1;

                pth=clas.path;
                sam=fullfile(pth,'sampleImage.png');

                if exist(sam)
                    im=imread(sam);

                else

                    [ k, himg]=clas.displayFormattedTrainingSet('Display','Nimages',9);
                    %    himg
                    im=himg;
                    close

                end
                selectedNode.NodeData=im;
            else
                im=selectedNode.NodeData;
            end

            imshow(im,'parent',app.UIAxes);
        end

   function registerRecentProject(app, projectPath)

    % --- Normaliser input -> string scalar
    projectPath = string(projectPath);
    projectPath = projectPath(1);

    % --- Purge + normaliser liste existante -> string colonne
    app.cleanRecentProjectsList();

    old = app.RecentProjects;
    if isempty(old)
        old = string.empty(0,1);
    else
        if iscell(old), old = string(old); end
        old = string(old);
        old = old(:);
    end

    % --- Prepend + dédoublonner (garde le plus récent)
    newList = [projectPath; old];
    newList = unique(newList, 'stable');

    % --- Limiter
    maxN = 10;
    if numel(newList) > maxN
        newList = newList(1:maxN);
    end

    app.RecentProjects = newList;

    % --- Sauver sur disque
    try
        RecentProjects = app.RecentProjects; %#ok<NASGU>
        save(app.RecentProjectsFile, 'RecentProjects');
    catch ME
        warning('Could not save recent projects list: %s', ME.message);
    end

    % --- Refresh menu
    app.refreshRecentProjectsMenu();
end


function registerRecentClassifier(app, classiMatPath)
    % classiMatPath = chemin absolu vers le *_classification.mat

    % --- Normaliser input -> string scalar
    classiMatPath = string(classiMatPath);
    classiMatPath = classiMatPath(1);

    % --- Purge + normaliser liste existante -> string colonne
    app.cleanRecentClassifiersList();

    old = app.RecentClassifiers;
    if isempty(old)
        old = string.empty(0,1);
    else
        if iscell(old), old = string(old); end
        old = string(old);
        old = old(:);
    end

    % --- Prepend + dédoublonner (garde le plus récent)
    newList = [classiMatPath; old];
    newList = unique(newList, 'stable');

    % --- Limiter
    maxN = 10;
    if numel(newList) > maxN
        newList = newList(1:maxN);
    end

    app.RecentClassifiers = newList;

    % --- Sauver sur disque
    try
        RecentClassifiers = app.RecentClassifiers; %#ok<NASGU>
        save(app.RecentClassifiersFile, 'RecentClassifiers');
    catch ME
        warning('Could not save recent classifiers list: %s', ME.message);
    end

    % --- Refresh menu
    app.refreshRecentClassifiersMenu();
end


function refreshRecentClassifiersMenu(app)

    % Vider l'ancien contenu
    if ~isempty(app.OpenrecentClassiMenu.Children)
        delete(app.OpenrecentClassiMenu.Children);
    end

    if isempty(app.RecentClassifiers)
        uimenu(app.OpenrecentClassiMenu, ...
            'Text','(No recent classifiers)', ...
            'Enable','off');
        return;
    end

    for k = 1:numel(app.RecentClassifiers)
        thisClassiPath = app.RecentClassifiers(k);

        [~, shortName, ext] = fileparts(thisClassiPath);
        shortName = shortName + ext;  % e.g. "yolo_1_classification.mat"

        label = sprintf('%d. %s', k, shortName);

        uimenu(app.OpenrecentClassiMenu, ...
            'Text', label, ...
            'Tooltip', char(thisClassiPath), ...
            'MenuSelectedFcn', @(src,evt)app.openRecentClassifierCallback(thisClassiPath));
    end

    uimenu(app.OpenrecentClassiMenu, ...
        'Separator','on', ...
        'Text','Clear recent list', ...
        'MenuSelectedFcn', @(src,evt)app.clearRecentClassifiers());
end


        function refreshRecentProjectsMenu(app)

            if ~isempty(app.OpenrecentMenu.Children)
                delete(app.OpenrecentMenu.Children);
            end

            if isempty(app.RecentProjects)
                uimenu(app.OpenrecentMenu, ...
                    'Text','(No recent projects)', ...
                    'Enable','off');
                return;
            end

            for k = 1:numel(app.RecentProjects)
                thisPath = app.RecentProjects(k);
                
                % complet avec .mat

                [~, shortName, ext] = fileparts(thisPath);
                shortName = shortName + ext;  % ex: tmpProject.mat

                label = sprintf('%d. %s', k, shortName);

                uimenu(app.OpenrecentMenu, ...
                    'Text', label, ...
                    'Tooltip', char(thisPath), ...
                    'MenuSelectedFcn', @(src,evt)app.openRecentProjectCallback(thisPath));
            end

            uimenu(app.OpenrecentMenu, 'Separator','on', ...
                'Text','Clear recent list', ...
                'MenuSelectedFcn', @(src,evt)app.clearRecentProjects());
        end


function openRecentProjectCallback(app, projectPath)

    % force scalaire string -> char pour être sûr
    if isstring(projectPath)
        projectPath = projectPath(1); % prend juste le premier élément si jamais c'est un array
        projectPathChar = char(projectPath);
    else
        projectPathChar = projectPath; % déjà char
    end

    if ~isfile(projectPathChar)
        uialert(app.DetecDivUIFigure, ...
            sprintf('Project not found:\n%s\nIt will be removed from recent list.', projectPathChar), ...
            'Missing project', ...
            'Icon','warning');

        app.cleanRecentProjectsList();
        app.refreshRecentProjectsMenu();
        return;
    end

    d = uiprogressdlg(app.DetecDivUIFigure, ...
        'Title','Please Wait...', ...
        'Message','Loading selected project...');
    d.Value = 0.33;

    [proj, msg] = shallowLoad(projectPathChar);

    if isempty(proj)
        close(d);
        if ~isempty(msg)
            uialert(app.DetecDivUIFigure, msg, 'Error', 'Icon', 'error');
        end
        return;
    end

    d.Value = 0.66;
    pause(0.2);

    name = proj.io.file;
    assignin('base', name, proj);

    % on réenregistre le chemin propre (pas le tableau chelou)
    app.registerRecentProject(string(projectPathChar));

    gatherVarsFromWorkspace(app);
    displayNodes(app);

    close(d);
end


function openRecentClassifierCallback(app, classiMatPath)

    % sécuriser en char scalaire
    if isstring(classiMatPath)
        classiMatPath = char(classiMatPath(1));
    end

    if ~isfile(classiMatPath)
        uialert(app.DetecDivUIFigure, ...
            sprintf('Classifier not found:\n%s\nIt will be removed from recent list.', classiMatPath), ...
            'Missing classifier', ...
            'Icon','warning');

        app.cleanRecentClassifiersList();
        app.refreshRecentClassifiersMenu();
        return;
    end

    d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
        'Message','Loading selected classifier...');
    d.Value = 0.33;

    % recharge via classiLoad(fullpath)
    [classiObj, msg] = classiLoad(classiMatPath);

    if isempty(classiObj)
        close(d);
        if ~isempty(msg)
            uialert(app.DetecDivUIFigure,msg,'Error','Icon','error');
        end
        return;
    end

    d.Value = 0.66;
    pause(0.2);

    % publier dans base workspace, cohérent avec ta callback existante
    name = [classiObj.strid '_indep'];
    assignin('base', name, classiObj);

    % le remettre en tête de la liste "récents"
    app.registerRecentClassifier(classiMatPath);

    % refresh l'état interne / arbres etc.
    gatherVarsFromWorkspace(app);
    displayNodes(app);

    close(d);
end

function cleanRecentClassifiersList(app)
    rc = string(app.RecentClassifiers(:));
    rc = rc(rc ~= "");  % vire les vides

    keep = false(size(rc));
    for k = 1:numel(rc)
        keep(k) = isfile(rc(k));  % on ne garde que les chemins encore valides
    end
    rc = rc(keep);

    % unicité (conserve l'ordre LRU)
    [~, ia] = unique(rc, 'stable');
    rc = rc(sort(ia,'ascend'));

    % max 10
    if numel(rc) > 10
        rc = rc(1:10);
    end

    app.RecentClassifiers = rc;
end


         function cleanRecentProjectsList(app)
        rp = string(app.RecentProjects(:));
        rp = rp(rp ~= "");

        keep = false(size(rp));
        for k = 1:numel(rp)
            keep(k) = isfile(rp(k)); % le .mat doit exister encore
        end
        rp = rp(keep);

        % unicité en préservant l'ordre
     [~, ia] = unique(rp, 'stable');
rp = rp(ia);


        % max 10
        if numel(rp) > 10
            rp = rp(1:10);
        end

        app.RecentProjects = rp;
         end

   function clearRecentClassifiers(app)
    choice = uiconfirm(app.DetecDivUIFigure, ...
        'Clear the list of recent classifiers ?', ...
        'Confirm', ...
        'Options', {'Yes','No'}, ...
        'DefaultOption','No', ...
        'CancelOption','No');

    if strcmp(choice,'Yes')
        app.RecentClassifiers = strings(0);

        try
            RecentClassifiers = app.RecentClassifiers; %#ok<NASGU>
            save(app.RecentClassifiersFile, 'RecentClassifiers');
        catch ME
            warning('Could not save cleared recent classifiers list: %s', ME.message);
        end

        app.refreshRecentClassifiersMenu();
    end
end


        function clearRecentProjects(app)
            choice = uiconfirm(app.DetecDivUIFigure, ...
                'Clear the list of recent projects ?', ...
                'Confirm', ...
                'Options', {'Yes','No'}, ...
                'DefaultOption','No', ...
                'CancelOption','No');

            if strcmp(choice,'Yes')
                app.RecentProjects = strings(0);

                % sauver la liste vide
                try
                    RecentProjects = app.RecentProjects; %#ok<NASGU>
                    save(app.RecentProjectsFile, 'RecentProjects');
                catch ME
                    warning('Could not save cleared recent list: %s', ME.message);
                end

                app.refreshRecentProjectsMenu();
            end
        end


       function initRecentProjectsSystem(app)
    % Détermine le dossier repo (un cran au-dessus de GUI/)
   % thisAppFile = mfilename('fullpath');  % .../GUI/detecdiv
   % [guiFolder, ~, ~] = fileparts(thisAppFile);
    repoFolder = prefdir; %fileparts(guiFolder);    % remonte d'un cran

    % Fichiers de persistance
    app.RecentProjectsFile     = fullfile(repoFolder, 'recentProjects.mat');
    app.RecentClassifiersFile  = fullfile(repoFolder, 'recentClassifiers.mat');

    % Charger projets
    if exist(app.RecentProjectsFile, 'file')
        S = load(app.RecentProjectsFile, 'RecentProjects');
        if isfield(S,'RecentProjects') && ~isempty(S.RecentProjects)
            app.RecentProjects = string(S.RecentProjects(:));
        end
    end

    % Charger classifieurs
    if exist(app.RecentClassifiersFile, 'file')
        S2 = load(app.RecentClassifiersFile, 'RecentClassifiers');
        if isfield(S2,'RecentClassifiers') && ~isempty(S2.RecentClassifiers)
            app.RecentClassifiers = string(S2.RecentClassifiers(:));
        end
    end

    % Nettoyer / rafraîchir menus
    app.cleanRecentProjectsList();
    app.refreshRecentProjectsMenu();

    app.cleanRecentClassifiersList();
    app.refreshRecentClassifiersMenu();
end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            checkInstalledToolboxes;
            initUserPreferences;

            initRecentProjectsSystem(app);


            gatherVarsFromWorkspace(app)
            displayNodes(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;

            app.AdddataButton.Visible='off';
            app.AddclassifierButton.Visible='off';

            app.UpdaterawdatapathButton.Visible='off';
            app.IdentifyROIsinpositionsButton.Visible='off';
            app.ExtractROIhypervolumesButton.Visible='off';

            app.ClassifydataButton.Visible='off';
            %    app.ClassifyprocessdataLabel.Visible='off';
            %      app.CreateorimportaclassifierprocessorLabel.Visible='off';
            %      app.IdentfyandextractregionsofinterestROIsindataLabel.Visible='off';
            %     app.AdddataintheprojectiepositionLabel.Visible='off';
            %  app.ProjectInformationLabel.Visible='off';
            app.AddprocessorButton.Visible='off';
            app.ProcessdataButton.Visible='off';
            app.OpenButton.Visible='off';
            cla( app.UIAxes);
            app.UIAxes.Visible='off';

            % HERE

            if numel(selectedNodes)==0
                return
            end

            if numel(selectedNodes.Tag)==0 & strcmp(selectedNodes.Text,'Projects')
                app.ProjectsPanel.Title='';
                app.ProjectInformationLabel.Text='';
            end
            if numel(selectedNodes.Tag)==0 & strcmp(selectedNodes.Text,'Independent Classifiers')
                app.ProjectsPanel.Title='';
                app.ProjectInformationLabel.Text='';
            end
            if numel(selectedNodes.Tag)==0 & strcmp(selectedNodes.Text,'Independent Pipelines')
                app.ProjectsPanel.Title='';
                app.ProjectInformationLabel.Text='';
            end

            if strcmp(selectedNodes.Tag,'Project')

                gatherVarsFromWorkspace(app);
                % displayNodes(app)

                app.ProjectsPanel.Title='Project';
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};
                shallowObj=evalin('base',proj);

                t='';
                t=[t 'Project path: ' newline newline];
                t=[t shallowObj.io.path shallowObj.io.file '.mat' newline newline];

                if numel(shallowObj.fov)==1 & numel(shallowObj.fov(1).srcpath{1})==0
                    n=0;
                else
                    n= numel(shallowObj.fov);
                end

                t=[t 'Number of positions: ' num2str(n) newline newline];

                t=[t 'Number of classifiers in project: ' num2str(numel(shallowObj.processing.classification)) newline newline];
                t=[t 'Number of pipeline runs in project: ' num2str(numel(shallowObj.processing.pipeline)) newline newline];
                app.ProjectInformationLabel.Text=t;

                app.AdddataButton.Visible='on';
                app.AddclassifierButton.Visible='on';
                %                app.CheckrawdatapathButton.Visible='on';
                app.UpdaterawdatapathButton.Visible='on';
                app.IdentifyROIsinpositionsButton.Visible='on';
                app.ExtractROIhypervolumesButton.Visible='on';

                app.ClassifydataButton.Visible='on';
                %   app.ClassifyprocessdataLabel.Visible='on';
                %     app.CreateorimportaclassifierprocessorLabel.Visible='on';
                %     app.IdentfyandextractregionsofinterestROIsindataLabel.Visible='on';
                %     app.AdddataintheprojectiepositionLabel.Visible='on';
                % app.ProjectInformationLabel.Visible='on';
                app.AddprocessorButton.Visible='on';
                app.ProcessdataButton.Visible='on';

            else

            end

            if strcmp(selectedNodes.Tag,'Projectpos')

                app.ProjectsPanel.Title='Position';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Position...';

                cc=app.Tree.SelectedNodes.UserData;

                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                position=shallowObj.fov(pos);

                % display sub,odes

                if numel(app.Tree.SelectedNodes.Children)==0
                    if numel(app.Data.Projectposrois{cc(1)})

                        for n=1:numel(app.Data.Projectposrois{cc(1)}{cc(2)})

                            % aa=app.Data.Projectclassirois{i}{k}{n}
                            %   cm=uicontextmenu(app.DetecDivUIFigure);
                            %  m = uimenu(cm,'Text','Open ROI...');
                            %  m.MenuSelectedFcn={@contextMenuROIFcn,[i,k,n],'Projectposrois'};
                            [pth fle ext]= fileparts(which('detecdiv.mlapp'));
                            uitreenode(app.Tree.SelectedNodes,'Text',app.Data.Projectposrois{cc(1)}{cc(2)}{n},'Tag','Projectposrois','UserData',[cc(1),cc(2),n],'Icon',fullfile(pth,'roi.png'));
                            % disabled because too heavy with large projects
                        end
                    end
                end


                % display text

                t='';
                t=[t 'Source files path: ' newline newline];

                for i=1%:numel(position.srcpath)
                    t=[t position.srcpath{i} newline newline];
                end

                t=[t 'Source sample filename: ' newline newline];
                t=[t position.srclist{1}(1).name newline newline];

                t=[t num2str(numel(position.channel)) ' channels: '];

                for i=1:numel(position.channel)
                    t=[t position.channel{i} ' '];
                end

                t=[t newline newline];
                if numel(position.frames)
                    fr=position.frames;
                else
                    fr=numel(position.srclist{1});
                end
                t=[t num2str(fr) ' frames' newline newline];

                n=numel(position.roi);
                if n==1 & numel(position.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as available datasets' newline];

                if n==0
                    t=[t 'You must first define one or several ROIs by opening the raw data viewer!' newline newline];
                end
                app.ProjectInformationLabel.Text=t;

            end


            if strcmp(selectedNodes.Tag,'Projectclassi')
                app.ProjectsPanel.Title='Classifier';

                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Classifier...';

                app.UIAxes.Visible='on';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);

                if numel(app.Tree.SelectedNodes.Children)==0
                    if numel(app.Data.Projectclassirois{cc(1)})
                        [pth fle ext]= fileparts(which('detecdiv.mlapp'));
                        for n=1:numel(app.Data.Projectclassirois{cc(1)}{cc(2)})
                            % aa=app.Data.Projectclassirois{i}{k}{n}
                            cm=uicontextmenu(app.DetecDivUIFigure);
                            m = uimenu(cm,'Text','Open ROI...');
                            m.MenuSelectedFcn={@contextMenuROIFcn,[cc(1),cc(2),n],'Projectclassirois'};
                            %  ''ContextMenu',cm'
                            uitreenode(app.Tree.SelectedNodes,'Text',app.Data.Projectclassirois{cc(1)}{cc(2)}{n},'Tag','Projectclassirois','UserData',[cc(1),cc(2),n],'Icon',fullfile(pth,'roi.png'));
                            % disabled because too heavy with large projects
                        end
                    end
                end


                t='';
                t=[t 'Classification path: ' newline];
                t=[t clas.path newline newline];

                if numel(clas.description)
                    t=[t 'Description: '];

                    t=[t clas.description{1}];
                end

                if numel(clas.category)
                    t=[t clas.category{1} char(13)];
                end

                if numel(clas.classes)
                    t=[t 'Classes: '];

                    for i=1:numel(clas.classes)
                        t=[t clas.classes{i}];
                    end
                end

                t=[t  char(13)];

                n=numel(clas.roi);
                if n==1 & numel(clas.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as training/test sets'  newline];

                if n==0
                    t=[t 'You must first import ROIs ! For this, first open the classifier.' newline newline];
                end

                app.ProjectInformationLabel.Text=t;
                displayClassiImage(app,clas);
            end

            if strcmp(selectedNodes.Tag,'Projectprocess')
                app.ProjectsPanel.Title='Processor';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Processor...';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.processor(pos);

                %                 if numel(app.Tree.SelectedNodes.Children)==0
                %                  if numel(app.Data.Projectclassirois{cc(1)})
                %                  [pth fle ext]= fileparts(which('detecdiv.mlapp'));
                %                     for n=1:numel(app.Data.Projectclassirois{cc(1)}{cc(2)})
                %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                %                       cm=uicontextmenu(app.DetecDivUIFigure);
                %                     m = uimenu(cm,'Text','Open ROI...');
                %                     m.MenuSelectedFcn={@contextMenuROIFcn,[cc(1),cc(2),n],'Projectclassirois'};
                %                   %  ''ContextMenu',cm'
                %                         uitreenode(app.Tree.SelectedNodes,'Text',app.Data.Projectclassirois{cc(1)}{cc(2)}{n},'Tag','Projectclassirois','UserData',[cc(1),cc(2),n],'Icon',fullfile(pth,'roi.png'));
                %                                     % disabled because too heavy with large projects
                %                     end
                %                  end
                %                 end


                t='';
                t=[t 'Processor path: ' newline];
                t=[t clas.path newline newline];

                if numel(clas.description)
                    t=[t 'Description: '];

                    %  t=[t clas.description{1}];
                end

                %                 if numel(clas.category)
                %                 t=[t clas.category{1} char(13)];
                %                 end


                t=[t  char(13)];


                app.ProjectInformationLabel.Text=t;
            end


            if strcmp(selectedNodes.Tag,'Projectpipeline')
                app.ProjectsPanel.Title='Pipeline run';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Pipeline Run...';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                runObj=shallowObj.processing.pipeline(pos);

                t='';
                t=[t 'Run path: ' newline];
                t=[t runObj.path newline newline];

                if isprop(runObj,'templateId') && ~isempty(runObj.templateId)
                    t=[t 'Template: ' char(string(runObj.templateId)) newline];
                end
                if isprop(runObj,'status') && ~isempty(runObj.status)
                    t=[t 'Status: ' char(string(runObj.status)) newline];
                end
                if isprop(runObj,'runId') && ~isempty(runObj.runId)
                    t=[t 'Run ID: ' char(string(runObj.runId)) newline];
                end

                app.ProjectInformationLabel.Text=t;
            end

            if strcmp(selectedNodes.Tag,'Pipeline')
                app.ProjectsPanel.Title='Pipeline';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Pipeline...';

                cc=app.Tree.SelectedNodes.UserData;
                varName=app.Data.Pipeline{cc};
                pipeObj=evalin('base',varName);

                t='';
                t=[t 'Pipeline path: ' newline];
                t=[t pipeObj.path newline newline];

                if numel(pipeObj.description)
                    t=[t 'Description: ' char(string(pipeObj.description)) newline];
                end
                if numel(pipeObj.version)
                    t=[t 'Version: ' char(string(pipeObj.version)) newline];
                end
                if isprop(pipeObj,'nodes')
                    t=[t 'Nodes: ' num2str(numel(pipeObj.nodes)) newline];
                end

                app.ProjectInformationLabel.Text=t;
            end

            if strcmp(selectedNodes.Tag,'Classifier')
                app.ProjectsPanel.Title='Classifier';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Classifier...';

                app.UIAxes.Visible='on';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{cc};

                clas=evalin('base',proj);


                if numel(app.Tree.SelectedNodes.Children)==0
                    if numel(app.Data.Classifierrois{cc})

                        for n=1:numel(app.Data.Classifierrois{cc})
                            % aa=app.Data.Projectclassirois{i}{k}{n}
                            cm=uicontextmenu(app.DetecDivUIFigure);
                            m = uimenu(cm,'Text','Open ROI...');
                            m.MenuSelectedFcn={@contextMenuROIFcn,[cc,n],'Projectposrois'};
                            %  'ContextMenu',cm
                            [pth fle ext]= fileparts(which('detecdiv.mlapp'));

                            uitreenode(app.Tree.SelectedNodes,'Text',app.Data.Classifierrois{cc}{n},'Tag','Classifierrois','UserData',[cc,n],'Icon',fullfile(pth,'roi.png'));
                            % disabled because too heavy with large projects
                        end
                    end
                end

                t='';
                t=[t 'Classification path: ' newline newline];
                t=[t clas.path newline newline];

                if numel(clas.description)
                    t=[t 'Description: '];

                    t=[t clas.description{1} ' - '];
                end

                if numel(clas.category)
                    t=[t clas.category{1} newline newline];
                end

                if numel(clas.classes)

                    t=[t 'Classes: ' newline newline];

                    for i=1:numel(clas.classes)
                        t=[t clas.classes{i} newline];
                    end
                end
                t=[t newline];
                n=numel(clas.roi);
                if n==1 & numel(clas.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as training/test sets' newline newline];

                if n==0
                    t=[t 'You must first import ROIs ! For this, first open the classifier.' newline newline];
                end

                app.ProjectInformationLabel.Text=t;

                if numel(clas.category)
                    if clas.category{1}~="Timeseries"
                        displayClassiImage(app,clas);
                    end
                end
            end

            if strcmp(selectedNodes.Tag,'Projectposrois') || strcmp(selectedNodes.Tag,'Projectclassirois') || strcmp(selectedNodes.Tag,'Classifierrois')

                arg=app.Tree.SelectedNodes.UserData;

                if strcmp(selectedNodes.Tag,'Projectclassirois')
                    cc=arg(1);
                    pos=arg(2);
                    ro=arg(3);


                    proj=app.Data.Project{cc};
                    shallowObj=evalin('base',proj);
                    roiObj=shallowObj.processing.classification(pos).roi(ro);
                    roiObj.parent=shallowObj.processing.classification(pos);
                end

                if strcmp(selectedNodes.Tag,'Projectposrois')
                    cc=arg(1);
                    pos=arg(2);
                    ro=arg(3);
                    proj=app.Data.Project{cc};
                    shallowObj=evalin('base',proj);
                    roiObj=shallowObj.fov(pos).roi(ro);
                    roiObj.parent=shallowObj.fov(pos);
                end

                if strcmp(selectedNodes.Tag,'Classifierrois')
                    cc=arg(1);
                    ro=arg(2);
                    clas=app.Data.Classifier{cc};
                    clas=evalin('base',clas);

                    roiObj=clas.roi(ro);

                    roiObj.parent=clas;
                end


                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Loading selected ROI...');
                d.Value=0.33;

                %roiObj.view;

                if numel(roiObj.image)==0

                    roiObj.load;
                end

                if numel(roiObj.image)==0
                    disp('Cannot open ROI obj; Quitting...')
                    return;
                end

                switch selectedNodes.Tag
                    case 'Projectclassirois'
                        roiObj.parent=shallowObj.processing.classification(pos);
                    case 'Projectposrois'
                        roiObj.parent=shallowObj.fov(pos);
                    case 'Classifierrois'
                        roiObj.parent=clas;
                end

                figures=findall(0,'Type','figure');
                appFigure=findobj(figures,'Name','ScoreApp');
                if isprop(appFigure,'RunningAppInstance')
                    appFigure.RunningAppInstance.addROI(roiObj);
                else
                    score(roiObj);
                end

                d.Value=0.67;

                if numel(roiObj.image)==0
                    d.Message='Cannot access ROI file, or ROI data have not yet been extracted... Quitting!';
                    pause(2);
                else
                    d.Message='Loading ROI successful!';
                end
                pause(0.2);
                close(d)
            end


        end

        % Callback function
        function ContextMenuOpening(app, event)

        end

        % Callback function
        function OpenMenuSelected(app, event)

        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
            proj=shallowNew;
            if numel(proj)==0
                return;
            end
            name=proj.io.file;
            assignin('base',name,proj);
            gatherVarsFromWorkspace(app);
            displayNodes(app)
        end

        % Callback function
        function DetecDivUIFigureWindowButtonDown(app, event)

        end

        % Callback function
        function DetecDivUIFigureButtonDown(app, event)

        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)

            d = uiprogressdlg(app.DetecDivUIFigure, ...
                'Title','Please Wait...', ...
                'Message','Loading selected project...');

            d.Value = 0.33;

            [proj, msg] = shallowLoad;
            if isempty(proj)
                close(d);
                if ~isempty(msg)
                    uialert(app.DetecDivUIFigure, msg, 'Warning', 'Icon', 'warning');
                end
                return;
            end

            d.Value = 0.66;
            pause(0.2);  % (garde si tu veux forcer l'update graphique)

            % mettre l'objet dans le workspace base sous son nom
            name = proj.io.file;
            assignin('base', name, proj);

            % chemin absolu du .mat du projet

            projectPathChar = fullfile(proj.io.path, [proj.io.file '.mat']);
            app.registerRecentProject(string(projectPathChar));

            % mettre à jour l'état interne de l'app
            gatherVarsFromWorkspace(app);
            displayNodes(app);

            % éventuellement, vérifier les chemins images (tu l'as commenté)
            % check = checkImagePath(app,proj);
            % if any(check)==0
            %     warnmsg = ['Those positions have an incorrect base path.' newline ...
            %                'You must update the path if you plan to build new region of interest / process the raw data !'];
            %     uialert(app.DetecDivUIFigure, warnmsg, 'Warning', 'Icon','warning');
            % end

            close(d);


        end

        % Menu selected function: Closeproject
        function CloseprojectMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                evalin('base',['clear ' proj]);
                gatherVarsFromWorkspace(app);
                displayNodes(app)
            end
        end

        % Menu selected function: SaveselectedprojectMenu
        function SaveselectedprojectMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                shallowObj=evalin('base',proj);

                if numel(shallowObj)==0
                    uialert(app.DetecDivUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                end

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Saving selected project...');
                d.Value=0.1;
                pause(0.5)
                shallowSave(shallowObj,[],d);
                close(d);
            else
                uialert(app.DetecDivUIFigure,'No project was selected in the window!','Error','Icon','warning');
            end

        end

        % Button pushed function: AddclassifierButton
        function AddclassifierButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                shallowObj=evalin('base',proj);

                classifierImporterGUI(app,shallowObj)
                uiwait(app.DetecDivUIFigure)
                TreeSelectionChanged(app, event)
                gatherVarsFromWorkspace(app);
                displayNodes(app);
            end
        end

        % Button pushed function: AdddataButton
        function AdddataButtonPushed(app, event)

            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end
            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            addDataGUI(shallowObj,app);

            uiwait(app.DetecDivUIFigure);

            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);


            displayNodes(app);

            % app.Tree.SelectedNodes=store;

        end

        % Button pushed function: UpdaterawdatapathButton
        function UpdaterawdatapathButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            shallowObj.setSrcPath;
            check = checkImagePath(app,shallowObj);

            %  pix=find(check==0);

            %  warndlg(['These positions have an incorrect path : ' num2str(pix)]);


        end

        % Callback function
        function CheckrawdatapathButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            check = checkImagePath(app,shallowObj);
        end

        % Menu selected function: NewprojectindependentclassiiferMenu
        function NewprojectindependentclassiiferMenuSelected(app, event)
            %classifierGUI([],app)
            classifierImporterGUI(app,[])
            uiwait(app.DetecDivUIFigure)
            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Menu selected function: NewpipelineMenu
        function NewpipelineMenuSelected(app, event)
            pipeObj = pipelineNew;
            if isempty(pipeObj)
                return;
            end

            baseList = evalin('base','who');
            name = pipeObj.strid;
            if any(strcmp(baseList,name))
                k = 1;
                newName = sprintf('%s_%d', name, k);
                while any(strcmp(baseList,newName))
                    k = k + 1;
                    newName = sprintf('%s_%d', name, k);
                end
                name = newName;
            end

            assignin('base', name, pipeObj);
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Menu selected function: OpenpipelineMenu
        function OpenpipelineMenuSelected(app, event)
            [pipeObj, msg] = pipelineLoad;
            if isempty(pipeObj)
                if ~isempty(msg)
                    uialert(app.DetecDivUIFigure, msg, 'Warning', 'Icon','warning');
                end
                return;
            end

            baseList = evalin('base','who');
            name = pipeObj.strid;
            if any(strcmp(baseList,name))
                k = 1;
                newName = sprintf('%s_%d', name, k);
                while any(strcmp(baseList,newName))
                    k = k + 1;
                    newName = sprintf('%s_%d', name, k);
                end
                name = newName;
            end

            assignin('base', name, pipeObj);
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Menu selected function: SavepipelineMenu
        function SavepipelineMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0 || ~strcmp(app.Tree.SelectedNodes.Tag,'Pipeline')
                uialert(app.DetecDivUIFigure,'First select an independent pipeline in the tree window!','Error');
                return;
            end

            cc = app.Tree.SelectedNodes.UserData;
            varName = app.Data.Pipeline{cc};
            pipeObj = evalin('base', varName);

            try
                pipelineSave(pipeObj);
            catch ME
                uialert(app.DetecDivUIFigure, ['Save failed: ' ME.message], 'Error', 'Icon','error');
            end
        end

        % Menu selected function: ClosepipelineMenu
        function ClosepipelineMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0 || ~strcmp(app.Tree.SelectedNodes.Tag,'Pipeline')
                uialert(app.DetecDivUIFigure,'First select an independent pipeline in the tree window!','Error');
                return;
            end

            cc = app.Tree.SelectedNodes.UserData;
            varName = app.Data.Pipeline{cc};
            evalin('base', ['clear ' varName]);
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Menu selected function: OpenprojectindependentclassifierMenu
        function OpenprojectindependentclassifierMenuSelected(app, event)
% Menu selected function: OpenprojectindependentclassifierMenu
    d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
        'Message','Loading selected classifier...');
    d.Value = 0.33;

    [classiObj, msg] = classiLoad;

    if isempty(classiObj)
        close(d);
        if ~isempty(msg)
            uialert(app.DetecDivUIFigure,msg,'Warning','Icon','warning');
        end
        return;
    end

    d.Value = 0.66;
    pause(0.2);

    % Publier dans le workspace
    name = [classiObj.strid '_indep'];
    assignin('base', name, classiObj);

    % Construire le chemin absolu du fichier .mat de ce classi,
    % pour l'ajouter à la liste des récents.
    % classiLoad a déjà fait:
    %   path = abspath;
    %   file = ...'_classification';
    %   classiObj.setPath([path '\'], file);
    %
    % Du coup:
    classiMatPath = fullfile(classiObj.path, [classiObj.strid '_classification.mat']);
    % Attention : classiObj.path finit avec '\' (ou '/'), et strid n'inclut pas "_classification" ?
    % On doit être 100% sûr. Sinon, plus robuste:
    %   classiObj.path -> "...\classification\yolo_1\"
    %   classiObj.strid -> "yolo_1"
    %   le fichier réel -> "yolo_1_classification.mat"
    %
    % Donc mieux :
    classiMatFilename = [classiObj.strid '_classification.mat'];
    classiMatFull = fullfile(classiObj.path, classiMatFilename);

    app.registerRecentClassifier(classiMatFull);

    gatherVarsFromWorkspace(app);
    displayNodes(app);

    close(d);

        end

        % Menu selected function: CloseprojectindependentclassifierMenu
        function CloseprojectindependentclassifierMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Classifier')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{i};

                evalin('base',['clear ' proj]);
                gatherVarsFromWorkspace(app);
                displayNodes(app)
            end
        end

        % Menu selected function: SaveprojectindependentclassifierMenu
        function SaveprojectindependentclassifierMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Classifier')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{i};

                classiObj=evalin('base',proj);

                if numel(classiObj)==0
                    uialert(app.DetecDivUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                    return;
                end

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Saving selected project...');
                d.Value=0.1;
                pause(0.5)
                classiSave(classiObj);
                d.Value=0.9;
                close(d);
            end

        end

        % Button pushed function: IdentifyROIsinpositionsButton
        function IdentifyROIsinpositionsButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);


            if numel(shallowObj.fov)==1 && numel(shallowObj.fov(1).srcpath)==0
                uialert(app.DetecDivUIFigure,'First add data and identify a pattern for ROI detection!','Error');
                return;
            end


            shallowObj.fov(1).view(shallowObj.fov(1).display.frame,[],shallowObj);
            obj=shallowObj.fov(1);

            %ROIextracterGUI(app,shallowObj);

            h=findall(groot,'Tag','ROIExtracter') ;
            if numel(h)==0
                ROIextracterGUI(obj);
            else
                ll= h.Children(2).Children(3); % update roi size
                ll.Text=['Pattern found in FOV Pos22_Pos1_1;  Size : ' num2str(obj.pattern(3)) 'x'  num2str(obj.pattern(4))];

                % update channel name
                pix=find(obj.display.selectedchannel,1,'first');
                h.Children(2).Children(1).Value=obj.channel{pix};


                figure(h);

            end


            %uiwait(app.DetecDivUIFigure)
            %gatherVarsFromWorkspace(app);
            %displayNodes(app);
        end

        % Button pushed function: ExtractROIhypervolumesButton
        function ExtractROIhypervolumesButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov(1).srcpath)==0
                uialert(app.DetecDivUIFigure,'There is not position avaialable for ROI extraction','Error');
                return;
            end

            if numel(shallowObj.fov(1).frames)==0
                nframes=numel(shallowObj.fov(1).srclist{1});
            else
                nframes=shallowObj.fov(1).frames(1);
            end

            npos=numel(shallowObj.fov);

            if numel(shallowObj.fov(1).interval)==0
                inte=ones(1,numel(shallowObj.fov(1).channel));
            else
                inte=shallowObj.fov(1).interval;
            end

            ncha=numel(shallowObj.fov(1).channel);


            % deffov=['1:'  num2str(numel(shallowObj.fov)) ];

         results = myDialog( ...
    {'Frames','Positions','Channels','XY_Drift_Correction','Scale'}, ...
    {['1:' num2str(nframes)], ['1:' num2str(npos)], ['1:' num2str(ncha)], false, 1}, ...
    'Tip', { ...
        'Frames to process (e.g. 1:100)', ...
        'Positions to process (e.g. 1:5)', ...
        'Channels to process (e.g. 1:2)', ...
        'Apply XY drift correction', ...
        'Scale factor for exported ROIs' }, ...
    'CallingApp', app.DetecDivUIFigure, ...
    'Title', 'Choose ROI extraction parameters');


            if numel(results)==0
                return;
            end


            %             prompt = {'Array of frames to process (leave this field blank if all frames should be processed):','Array of positions to process:',...
            %                 'Max number of frames in memory','Correct XY drift (0: no , 1: yes)',...
            %                 'Crash recovery (0: no; 1: yes)','Drift correction cropping factor (between 0 and 1):',...
            %                 'Array of channels to process'};%,'Period between frames for each channel (in frames units):'};
            %             dlgtitle = 'Input ROI extraction parameters';
            %
            %             dims = [1 100];

            crash=0;
            if exist(fullfile(userpath, 'tmpcrash.mat')) % a crash recovery file exists
                crash=1;
            end

            %      definput = {['1:' num2str(nframes)],['1:' num2str(npos)], '20', '0',num2str(crash),'1',['1:' num2str(ncha)]};%, num2str(inte)};
            %     answer = inputdlg(prompt,dlgtitle,dims,definput);

            %      if numel(answer)==0
            %          return;
            %      end

            selection=uiconfirm(app.DetecDivUIFigure,'Are you sure that you want to extract ROI data from raw images? All previous ROI data will be overwritten! This process may take a VERY long time...','Warning');

            %  answer

            if strcmp(selection,'OK')

                cou=[];
                for i=str2num(results.Positions) %1:numel(shallowObj.fov)
                    %             shallowObj.fov(i).interval=str2num(answer{4});
                    if numel(shallowObj.fov(i).roi(1).id)==0
                        cou=[cou i];
                    end
                end

                if numel(cou)
                    uialert(app.DetecDivUIFigure,['There is no ROI in position(s): ' newline num2str(cou) newline ' These positions will be skipped!'],'Warning');
                    % return;
                end

                fovs=setxor(str2num(results.Positions),cou);
                if numel(fovs)
                    if size(fovs,1)>size(fovs,2)
                        fovs=fovs';

                    end
                    %fov

                   % shallowObj.saveCroppedImages('frames',str2num(results.Frames),'fov',fovs,'cut',results.Frames_In_Memory,'correctdrift', logical(results.XY_Drift_Correction), 'crashrecovery',results.Crash_Recovery,'cropdrift',results.Drift_Cropping,'channel',str2num(results.Channels),'scale',results.Scale);%, 'channelint', str2num(answer{4}));
                    %shallowSave(shallowObj);

                    % --- New extraction routine (streaming HDF5 append) ---
shallowObj.extractAllROICrops( ...
    'Frames',        str2num(results.Frames), ...
    'FOVIndex',      fovs, ...
    'Channels',      str2num(results.Channels), ...
    'CorrectDrift',  logical(results.XY_Drift_Correction), ...
    'Scale',         results.Scale ...
    );


                end
                uialert(app.DetecDivUIFigure,'ROI extraction is complete!','Success','Icon','success');
            end


        end

        % Callback function
        function OpendatatosetROIsORtodefineanimagepatternButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            t=app.ProjectsNode.Children(i);
            expand(t);

            uialert(app.DetecDivUIFigure,'Browse data available in the tree menu, right-click Open on the desired dataset,','Warning')
        end

        % Button pushed function: ClassifydataButton
        function ClassifydataButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            classifyDataGUI(shallowObj);
        end

        % Callback function
        function SelectROItoviewDropDownValueChanged(app, event)
            value = app.SelectROItoviewDropDown.Value;

            % selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
        end

        % Menu selected function: RefreshtreewindowMenu
        function RefreshtreewindowMenuSelected(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Callback function
        function CreateSingleFullScreenROIinallpositionsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase all exisiting ROIs; Proceed?','Warning');


            if strcmp(selection,'OK')

                for i=1:numel(shallowObj.fov)
                    tmp=shallowObj.fov(i).readImage(1,1);
                    roival=[1 1 size(tmp,2) size(tmp,1)];
                    shallowObj.fov(i).roi=roi;
                    shallowObj.fov(i).addROI(roival,shallowObj.fov(i).id)

                end

            end


        end

        % Button pushed function: AddprocessorButton
        function AddprocessorButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                shallowObj=evalin('base',proj);

                ProcessorImporterGUI(app,shallowObj)
                uiwait(app.DetecDivUIFigure)


                TreeSelectionChanged(app, event)
                gatherVarsFromWorkspace(app);
                displayNodes(app);
            end
        end

        % Button pushed function: ProcessdataButton
        function ProcessdataButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            processDataGUI(shallowObj);
        end

        % Menu selected function: ExportprojecttoPhylocellMenu
        function ExportprojecttoPhylocellMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            export2phylocellGUI(shallowObj);
        end

        % Callback function
        function CreateFullFrameSingleROIMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            prompt = {'Positions in which ROIs will be created:'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Creating a single full frame ROI';

            dims = [1 100];


            definput = {deffov};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                return;
            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase all exisiting ROIs; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(answer{1});
                if numel(id)
                    for i=id
                        tmp=shallowObj.fov(i).readImage(1,1);
                        roival=[1 1 size(tmp,2) size(tmp,1)];
                        shallowObj.fov(i).roi=roi;
                        shallowObj.fov(i).addROI(roival,shallowObj.fov(i).id)

                    end
                end

                uialert(app.DetecDivUIFigure,'ROI data must now be extracted to be functional...','Warning');
                RefreshtreewindowMenuSelected(app)
            end
        end

        % Callback function
        function DeleteallROIsinallpositionsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return
            end


            selection=uiconfirm(app.DetecDivUIFigure,'This will erase all exisiting ROIs; Proceed?','Warning');

            if strcmp(selection,'OK')
                for i=1:numel(shallowObj.fov)
                    shallowObj.fov(i).roi=roi;
                end
            end


            RefreshtreewindowMenuSelected(app)
        end

        % Callback function
        function DeletePositionsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            prompt = {'Positions that will be deleted:'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Deleting positions';

            dims = [1 100];


            definput = {deffov};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                return;
            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase selected positions; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(answer{1});
                if numel(id)
                    pix=setxor(1:numel(shallowObj.fov),id);
                    shallowObj.fov=shallowObj.fov(pix);
                end

                RefreshtreewindowMenuSelected(app)
            end
        end

        % Callback function
        function SetPositionsorientationMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return
            end


            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            prompt = {'Positions that will be deleted:', 'Orientation in degrees (0,90,180,270):'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Set position orientation';

            dims = [1 100];


            definput = {deffov,'0'};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                return;
            end


            if numel(intersect(str2num(answer{2}),[0 90 180 270]))==0
                uialert(app.DetecDivUIFigure,'Orientation value is wrong; quitting !','Warning');
                return
            end

            id=str2num(answer{1});
            if numel(id)
                for i=1:numel(shallowObj.fov)
                    shallowObj.fov(i).orientation=str2num(answer{2});
                end
            end
        end

        % Callback function
        function AdjustROIssizeMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            defroi=num2str(shallowObj.fov(1).roi(1).value(3:4));
            prompt = {'Positions in which to change ROI size:','New Width/Height [width height]:'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Adjusting ROI size';

            dims = [1 100];


            definput = {deffov,defroi};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                disp('User canceled');
                return;
            end

            sz=str2num(answer{2});

            for i=1:numel(str2num(answer{1}))
                for j=1:numel(1:numel(shallowObj.fov(i).roi))
                    shallowObj.fov(i).roi(j).adjustROISize([0 0 sz(1) sz(2)]);
                end
            end
        end

        % Menu selected function: UserpreferencesMenu
        function UserpreferencesMenuSelected(app, event)
            preferencesGUI;
        end

        % Menu selected function: ClassifierRepositoryMenu
        function ClassifierRepositoryMenuSelected(app, event)
            classiRepository;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
          
    % --- Dialog box de patience (indéterminée)
    d = uiprogressdlg(app.DetecDivUIFigure, ...
        'Title', 'Please wait', ...
        'Message', 'Opening selection...', ...
        'Indeterminate', 'on');

    try
        selectedNodes = app.Tree.SelectedNodes;

        arg = selectedNodes.UserData;
        str = selectedNodes.Tag;
        cc  = arg(1);

        % --- CAS : Classifier
        if strcmp(str,'Classifier')
            d.Message = 'Opening classifier...';

            clas = app.Data.Classifier{cc};
            clas = evalin('base', clas);
            classifierGUI(clas);
        end

        % --- CAS : Projectclassi
        if strcmp(str,'Projectclassi')
            d.Message = 'Opening project classifier...';

            proj = app.Data.Project{cc(1)};
            pos  = arg(2);
            shallowObj = evalin('base', proj);
            clas = shallowObj.processing.classification(pos);
            classifierGUI(clas);
        end

        % --- CAS : Projectpos
        if strcmp(str,'Projectpos')
            d.Message = 'Loading image data...';

            cc  = arg(1);
            pos = arg(2);

            proj = app.Data.Project{cc(1)};
            shallowObj = evalin('base', proj);

            if numel(shallowObj.fov(pos).srcpath{1}) > 0
                im = readImage(shallowObj.fov(pos),1,1);

                if isempty(im)
                    close(d);
                    errordlg('The path to your raw data is incorrect; please update the raw data path !');
                    return
                end
            end

            d.Message = 'Opening field of view...';
            shallowObj.fov(pos).view( ...
                shallowObj.fov(pos).display.frame, [], shallowObj);
        end

        % --- CAS : Projectpipeline
if strcmp(str,'Projectpipeline')
    d.Message = 'Opening pipeline run...';

    proj = app.Data.Project{cc(1)};
    pos  = arg(2);
    shallowObj = evalin('base', proj);
    runObj = shallowObj.processing.pipeline(pos);

    jsonFile = fullfile(runObj.path, 'run.json');
    if exist(jsonFile,'file')
        edit(jsonFile);
    else
        uialert(app.DetecDivUIFigure, sprintf('run.json not found:\\n%s', jsonFile), 'Missing file', 'Icon','warning');
    end
end

        % --- CAS : Pipeline (independent)
if strcmp(str,'Pipeline')
    d.Message = 'Opening pipeline...';

    varName = app.Data.Pipeline{cc};
    pipeObj = evalin('base', varName);
    jsonFile = fullfile(pipeObj.path, 'pipeline.json');
    if exist(jsonFile,'file')
        edit(jsonFile);
    else
        uialert(app.DetecDivUIFigure, sprintf('pipeline.json not found:\\n%s', jsonFile), 'Missing file', 'Icon','warning');
    end
end

        % --- CAS : Projectprocess
      % --- CAS : Projectprocess
if strcmp(str,'Projectprocess')
    d.Message = 'Opening processing pipeline...';

    proj = app.Data.Project{cc(1)};
    pos  = arg(2);
    shallowObj = evalin('base', proj);
    proc = shallowObj.processing.processor(pos);

    % NEW: open processDataGUI with processObj preselected
    processDataGUI(shallowObj, proc);
end


    catch ME
        close(d);
        rethrow(ME)
    end

    % --- Fermeture propre
    close(d);


        end

        % Menu selected function: AddclassifierMenu
        function AddclassifierMenuSelected(app, event)
            AddclassifierButtonPushed(app, event)
        end

        % Menu selected function: SetFrameOrientationMenu
        function SetFrameOrientationMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return
            end


            deffov=['1:'  num2str(numel(shallowObj.fov)) ];

            results=myDialog({'Positions','Orientation'},{deffov,{'0','90','180','270','0'}},'Tip',{'Choose the list of positions to be processed','Orientation in deegrees'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose orientation of positions');

            if numel(results)==0
                return;
            end


            id=str2num(results.Positions);
            if numel(id)
                for i=1:numel(shallowObj.fov)
                    shallowObj.fov(i).orientation=str2num(results.Orientation{end});
                end
            end
        end

        % Menu selected function: CreateFullFrameROisMenu
        function CreateFullFrameROisMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];

            results=myDialog({'Positions','Number_of_ROIs_per_frame'},{deffov,{'1' '4' '9' '16' '25' '1'}},'Tip',{'Choose the list of positions to be processed','Choose how many ROIs to split the full frame into'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose parameters to create  ROIs that cover the whole frame ');

            if numel(results)==0
                return;
            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase all exisiting ROIs; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(results.Positions);
                nb=str2num(results.Number_of_ROIs_per_frame{end});

                if numel(id)
                    for j=id
                        tmp=shallowObj.fov(j).readImage(1,1);
                        %    roival=[1 1 size(tmp,2) size(tmp,1)];

                        rows = sqrt(nb);
                        cols = sqrt(nb);
                        k=nb;
                        N=size(tmp,1);
                        M=size(tmp,2);

                        % Calculate the size of each square
                        squareSizeRows = N / rows;
                        squareSizeCols = M / cols;

                        % Initialize cell array to store coordinates
                        coordinates = cell(k, 2);

                        shallowObj.fov(j).roi=roi;

                        for i = 1:k
                            % Calculate the row and column indices of the i-th square
                            rowIdx = ceil(i / cols);
                            colIdx = mod(i - 1, cols) + 1;

                            % Calculate the coordinates of the top-left corner
                            topLeftRow = (rowIdx - 1) * squareSizeRows + 1;
                            topLeftCol = (colIdx - 1) * squareSizeCols + 1;

                            % Calculate the coordinates of the bottom-right corner
                            bottomRightRow = min(rowIdx * squareSizeRows, N);
                            bottomRightCol = min(colIdx * squareSizeCols, M);

                            % Store the coordinates in the cell array
                            %  coordinates{i, 1} = [topLeftRow, topLeftCol];
                            %coordinates{i, 2} = [bottomRightRow, bottomRightCol];

                            roival= [topLeftCol, topLeftRow, bottomRightCol-topLeftCol+1,  bottomRightRow-topLeftRow+1];
                            roival=uint16(roival);
                            shallowObj.fov(j).addROI(roival,shallowObj.fov(j).id)
                        end

                    end
                end

                uialert(app.DetecDivUIFigure,'ROI data must now be extracted to be functional...','Warning','Icon','warning');
                RefreshtreewindowMenuSelected(app)
            end
        end

        % Menu selected function: DeleteROIsMenu
        function DeleteROIsMenuSelected(app, event)

    % 1. Vérifier qu'un projet est sélectionné
    if numel(app.Tree.SelectedNodes) == 0
        uialert(app.DetecDivUIFigure, ...
            'First select a project in the tree window!', ...
            'Error');
        return;
    end

    if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
        uialert(app.DetecDivUIFigure, ...
            'The selected node is not a project!', ...
            'Error');
        return;
    end

    % 2. Charger le projet (shallowObj) depuis base
    projIdx     = app.Tree.SelectedNodes.UserData;
    projVarName = app.Data.Project{projIdx};
    shallowObj  = evalin('base', projVarName);

    % Vérifier qu'il y a bien des FOVs utilisables
    if numel(shallowObj.fov) == 1 && numel(shallowObj.fov.srcpath) == 0
        uialert(app.DetecDivUIFigure, ...
            'There is no position available', ...
            'Warning');
        return;
    end

    nFOV = numel(shallowObj.fov);

    % Pour chaque FOV, on va :
    % - déterminer le nom affiché
    % - déterminer le default ROI deletion string "1:N"
    % - marquer la ligne comme sélectionnée par défaut (true)
    tableSelect   = false(nFOV,1);
    tableName     = cell(nFOV,1);
    tableROIQuery = cell(nFOV,1);

    for ff = 1:nFOV

        % 2a. Nom FOV pour l'affichage
        % On essaie de trouver quelque chose d'informatif
        fovLabel = sprintf('FOV #%d', ff);
        if isprop(shallowObj.fov(ff),'name') && ~isempty(shallowObj.fov(ff).name)
            fovLabel = sprintf('FOV #%d (%s)', ff, shallowObj.fov(ff).name);
        elseif isprop(shallowObj.fov(ff),'id') && ~isempty(shallowObj.fov(ff).id)
            fovLabel = sprintf('FOV #%d (%s)', ff, shallowObj.fov(ff).id);
        elseif isprop(shallowObj.fov(ff),'srcpath') && ~isempty(shallowObj.fov(ff).srcpath)
            [~,tmpn,~] = fileparts(shallowObj.fov(ff).srcpath);
            fovLabel = sprintf('FOV #%d (%s)', ff, tmpn);
        end
        tableName{ff} = fovLabel;

        % 2b. String par défaut des ROIs à supprimer : "1:N"
          if isprop(shallowObj.fov(ff),'roi') && ~isempty(shallowObj.fov(ff).roi)
        nROI = numel(shallowObj.fov(ff).roi);
        if nROI > 0
            if nROI == 1
                tableROIQuery{ff} = '1';
            else
                tableROIQuery{ff} = sprintf('1:%d', nROI);
            end
        else
            tableROIQuery{ff} = '';
        end
    else
        tableROIQuery{ff} = '';
    end

    % 2c. Sélection par défaut : vrai si le champ roi existe
    tableSelect(ff) = ~isempty(tableROIQuery{ff}) && ~strcmp(tableROIQuery{ff},'');
    end

    % 3. Construire la fenêtre modale
    dlg = uifigure('Name','Delete ROIs from Project', ...
                   'WindowStyle','modal', ...
                   'Position',[100 100 600 400]);

    % Layout global
    gl = uigridlayout(dlg, [3 1]);
    gl.RowHeight = {'1x',40,40};
    gl.ColumnWidth = {'1x'};

    % 3a. Table
    t = uitable(gl);
    t.Layout.Row = 1;
    t.Layout.Column = 1;

    % Data de base pour la table
    tData = [num2cell(tableSelect), tableName, tableROIQuery];

    % Définition des colonnes
    % Col1: checkbox editable logique
    % Col2: texte non éditable
    % Col3: texte éditable
    t.ColumnName = {'Select','FOV','ROIs to delete'};
    t.ColumnEditable = [true false true];
    t.Data = tData;

    % Forcer des largeurs raisonnables
    t.ColumnWidth = {60, 220, '1x'};

    % 3b. Ligne boutons Select/Deselect
    rowButtons = uigridlayout(gl,[1 3]);
    rowButtons.Layout.Row = 2;
    rowButtons.Layout.Column = 1;
    rowButtons.ColumnWidth = {100,120,'1x'};

    btnSelectAll = uibutton(rowButtons,'push', ...
        'Text','Select all', ...
        'ButtonPushedFcn',@(~,~) doSelectAll(true));

    btnDeselectAll = uibutton(rowButtons,'push', ...
        'Text','Deselect all', ...
        'ButtonPushedFcn',@(~,~) doSelectAll(false));

    % filler panel pour pousser à gauche
    uilabel(rowButtons,'Text',''); % just spacer

    % 3c. Ligne boutons OK / Cancel
    rowOK = uigridlayout(gl,[1 3]);
    rowOK.Layout.Row = 3;
    rowOK.Layout.Column = 1;
    rowOK.ColumnWidth = {'1x',80,80};

    % spacer
    uilabel(rowOK,'Text','');

    btnCancel = uibutton(rowOK,'push', ...
        'Text','Cancel', ...
        'ButtonPushedFcn',@(~,~) onCancel());

    btnOK = uibutton(rowOK,'push', ...
        'Text','OK', ...
        'ButtonPushedFcn',@(~,~) onOK());

    % On va utiliser uiwait/uiresume pour bloquer tant que la boîte est ouverte
    userChoice = struct('action','cancel','table',[]);
    uiwait(dlg);  % bloquant jusqu'à uiresume dans onOK/onCancel

    % À ce stade, soit OK, soit Cancel a défini userChoice
    if ~isvalid(dlg)
        % La figure a déjà été détruite dans le callback.
        % On lit userChoice.action via le nested scope (fermé dessus).
    end

    % Si cancel -> on sort sans rien changer
    if strcmp(userChoice.action,'cancel')
        return;
    end

    % 4. Application des suppressions ROI selon userChoice.table
    % userChoice.table(:,1) : logical select?
    % userChoice.table(:,3) : string "1:10" etc
    finalData = userChoice.table;

    for ff = 1:nFOV

        % skip si pas sélectionné
        if ~finalData{ff,1}
            continue;
        end

        if ~isprop(shallowObj.fov(ff),'roi') || isempty(shallowObj.fov(ff).roi)
            continue;
        end

        roiStr = strtrim(finalData{ff,3});
        if isempty(roiStr)
            % rien à supprimer
            continue;
        end

        % Parser la chaîne "1:32" etc en indices
        % On utilise eval de façon contrôlée: si l'utilisateur tape une
        % horreur, on catch et on ignore.
        try
            delIdx = eval(['[', roiStr, ']']); %#ok<EVLDIR>
            if ~isnumeric(delIdx)
                delIdx = [];
            end
        catch
            delIdx = [];
        end

        if isempty(delIdx)
            continue;
        end

        allIdx  = 1:numel(shallowObj.fov(ff).roi);
        keepIdx = setxor(allIdx, delIdx);

        if isempty(keepIdx)
            % plus aucune ROI
            shallowObj.fov(ff).roi = roi;
        else
            shallowObj.fov(ff).roi = shallowObj.fov(ff).roi(keepIdx);
        end
    end

    % 5. Sauvegarde de l'objet modifié dans le workspace
    assignin('base', projVarName, shallowObj);

    % 6. Refresh UI
    RefreshtreewindowMenuSelected(app);


    % ==========================
    %  NESTED CALLBACKS
    % ==========================
    function doSelectAll(state)
        % state=true -> tout à true
        % state=false -> tout à false
        tbl = t.Data;
        for r = 1:size(tbl,1)
            tbl{r,1} = state;
        end
        t.Data = tbl;
    end

    function onCancel()
        % L'utilisateur annule : rien à faire
        userChoice.action = 'cancel';
        userChoice.table  = t.Data;
        if isvalid(dlg)
            uiresume(dlg);
            delete(dlg);
        end
    end

    function onOK()
        % Vérifier qu'au moins une ligne est sélectionnée et qu'il y a des ROIs ?
        % (on ne rend pas ça bloquant pour ne pas le frustrer)
        userChoice.action = 'ok';
        userChoice.table  = t.Data;
        if isvalid(dlg)
            uiresume(dlg);
            delete(dlg);
        end
    end


        end

        % Menu selected function: AdjustROIsMenu
        function AdjustROIsMenuSelected(app, event)

            ROIAdjustGUI();
            
            % if numel(app.Tree.SelectedNodes)==0
            %     uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
            %     return;
            % end
            % 
            % if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
            %     uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
            %     return;
            % end
            % 
            % i=app.Tree.SelectedNodes.UserData;
            % % store=app.Tree.SelectedNodes;
            % proj=app.Data.Project{i};
            % shallowObj=evalin('base',proj);
            % 
            % 
            % deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            % defroi=num2str(shallowObj.fov(1).roi(1).value(3:4));
            % results=myDialog({'Positions','ROI_size'},{deffov,defroi},'Tip',{'Choose the list of positions to be processed','Enter new ROI width and height, eg.: 60 60'},...
            %     'CallingApp',app.DetecDivUIFigure,...
            %     'Title','Choose positions in which to adjust ROI size');
            % 
            % if numel(results)==0
            %     return;
            % end
            % 
            % 
            % 
            % sz=str2num(results.ROI_size);
            % 
            % for i=str2num(results.Positions)
            %     for j=1:numel(1:numel(shallowObj.fov(i).roi))
            %         shallowObj.fov(i).roi(j).adjustROISize([0 0 sz(1) sz(2)]);
            %     end
            % end
        end

        % Menu selected function: DeletePositionsMenu_2
        function DeletePositionsMenu_2Selected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            results=myDialog({'Positions'},{deffov},'Tip',{'Choose the list of positions to delete'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose positions to delete');

            if numel(results)==0
                return;
            end


            selection=uiconfirm(app.DetecDivUIFigure,'This will erase selected positions; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(results.Positions);
                if numel(id)
                    pix=setxor(1:numel(shallowObj.fov),id);
                    shallowObj.fov=shallowObj.fov(pix);
                end

                RefreshtreewindowMenuSelected(app)
            end
        end

        % Menu selected function: AddPositionsDataMenu
        function AddPositionsDataMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end
            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            addDataGUI(shallowObj,app);

            uiwait(app.DetecDivUIFigure);

            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);


            displayNodes(app);
        end

        % Callback function
        function CleanupROItrainingandresultsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end


            deffov=['1:'  num2str(numel(shallowObj.fov)) ];

            % listof training and results field.

            trainstr={};
            resultsstr={};
            cc=1;
            cc2=1;

            for i=1:numel(shallowObj.fov)
                for k=1:numel(shallowObj.fov(i).roi)
                    roiobj=shallowObj.fov(i).roi(k);

                    roiobj.load('results');

                    if numel(roiobj.train)

                        f=fieldnames(roiobj.train);

                        for j=1:numel(f)
                            trainstr{cc}=f{j};
                            cc=cc+1;
                        end
                    end
                    if numel(roiobj.results)
                        f=fieldnames(roiobj.results);

                        for j=1:numel(f)
                            resultsstr{cc2}=f{j};
                            cc2=cc2+1;
                        end
                    end
                end
            end

            trainstr=unique(trainstr);
            resultsstr=unique(resultsstr);

            % if numel(trainstr)==0
            trainstr=[trainstr '-' '-'];
            % end

            % if numel(resultsstr)==0
            resultsstr=[resultsstr '-' '-'];
            %  end

            results=myDialog({'Positions','Train_Fields','Results_Fields','Remove_All_Train','Remove_All_Results'},...
                {deffov,trainstr,resultsstr,false,false},...
                'Tip',{'Choose the positions to process','Choose the training field to remove','Choose the result field to remove','Check to remove all trainings','Check to remove all results'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose fields to remove from training and results');

            if numel(results)==0
                return;
            end


            for i=str2num(results.Positions)
                for k=1:numel(shallowObj.fov(i).roi)

                    roiobj=shallowObj.fov(i).roi(k);

                    if numel(roiobj.image)==0
                        roiobj.load;
                    end

                    if results.Remove_All_Train==1
                        roiobj.train=[];
                    else
                        tr=results.Train_Fields{end};
                        if ~strcmp(tr,'-')
                            roiobj.removeData({tr},{});
                        end
                    end
                    if results.Remove_All_Results==1
                        roiobj.results=[];
                    else
                        roiobj.load('results');
                        re=results.Results_Fields{end};
                        if ~strcmp(re,'-')
                            roiobj.removeData({},{re});
                        end
                    end

                    roiobj.save('results');
                end
            end


            RefreshtreewindowMenuSelected(app)


        end

        % Callback function
        function CleanupROIstrainingandresultsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            selectedNodes=  app.Tree.SelectedNodes;

            okclas=0;
            if strcmp(selectedNodes.Tag,'Projectclassi')
                okclas=1;
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
            end

            if strcmp(selectedNodes.Tag,'Projectpipeline')
                app.ProjectsPanel.Title='Pipeline run';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Pipeline Run...';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                runObj=shallowObj.processing.pipeline(pos);

                t='';
                t=[t 'Run path: ' newline];
                t=[t runObj.path newline newline];

                if isprop(runObj,'templateId') && ~isempty(runObj.templateId)
                    t=[t 'Template: ' char(string(runObj.templateId)) newline];
                end
                if isprop(runObj,'status') && ~isempty(runObj.status)
                    t=[t 'Status: ' char(string(runObj.status)) newline];
                end
                if isprop(runObj,'runId') && ~isempty(runObj.runId)
                    t=[t 'Run ID: ' char(string(runObj.runId)) newline];
                end

                app.ProjectInformationLabel.Text=t;
            end

            if strcmp(selectedNodes.Tag,'Pipeline')
                app.ProjectsPanel.Title='Pipeline';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Pipeline...';

                cc=app.Tree.SelectedNodes.UserData;
                varName=app.Data.Pipeline{cc};
                pipeObj=evalin('base',varName);

                t='';
                t=[t 'Pipeline path: ' newline];
                t=[t pipeObj.path newline newline];

                if numel(pipeObj.description)
                    t=[t 'Description: ' char(string(pipeObj.description)) newline];
                end
                if numel(pipeObj.version)
                    t=[t 'Version: ' char(string(pipeObj.version)) newline];
                end
                if isprop(pipeObj,'nodes')
                    t=[t 'Nodes: ' num2str(numel(pipeObj.nodes)) newline];
                end

                app.ProjectInformationLabel.Text=t;
            end

            if strcmp(selectedNodes.Tag,'Classifier')
                okclas=1;
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{cc};

                clas=evalin('base',proj);
            end

            if okclas==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            % listof training and results field.

            trainstr={};
            resultsstr={};
            cc=1;
            cc2=1;
            for k=1:numel(clas.roi)
                roiobj=clas.roi(k);

                roiobj.load('results');

                if numel(roiobj.train)

                    f=fieldnames(roiobj.train);

                    for j=1:numel(f)
                        trainstr{cc}=f{j};
                        cc=cc+1;
                    end
                end
                if numel(roiobj.results)
                    f=fieldnames(roiobj.results);

                    for j=1:numel(f)
                        resultsstr{cc2}=f{j};
                        cc2=cc2+1;

                    end
                end
            end

            trainstr=unique(trainstr);
            resultsstr=unique(resultsstr);

            % if numel(trainstr)==0
            trainstr=[trainstr '-' '-'];
            % end

            % if numel(resultsstr)==0
            resultsstr=[resultsstr '-' '-'];
            %  end

            results=myDialog({'Train_Fields','Results_Fields','Remove_All_Train','Remove_All_Results'},...
                {trainstr,resultsstr,false,false},...
                'Tip',{'Choose the training field to remove','Choose the result field to remove','Check to remove all trainings but the one selected above','Check to remove all results but the one selected above'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose fields to remove from training and results');

            if numel(results)==0
                return;
            end


            for k=1:numel(clas.roi)

                roiobj=clas.roi(k);
                if numel(roiobj.image)==0
                    roiobj.load;
                end

                if results.Remove_All_Train==1
                    tr=results.Train_Fields{end};
                    if ~strcmp(tr,'-')
                        tmp=setxor(trainstr,tr);
                        roiobj.removeData(tmp,{});
                    else
                        roiobj.train=[];
                    end
                else
                    tr=results.Train_Fields{end};
                    if ~strcmp(tr,'-')
                        roiobj.removeData({tr},{});
                    end
                end

                if results.Remove_All_Results==1
                    tr=results.Results_Fields{end};
                    if ~strcmp(tr,'-')
                        tmp=setxor(resultsstr,tr);
                        roiobj.removeData({},tmp);
                    else

                        roiobj.results=[];
                    end
                else
                    roiobj.load('results');
                    re=results.Results_Fields{end};
                    if ~strcmp(re,'-')
                        roiobj.removeData({},{re});
                    end
                end

                roiobj.save;

            end


            RefreshtreewindowMenuSelected(app)

        end

        % Callback function
        function ChangeChannelnameMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);


            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            listChannels=listAvailableChannels;
            listChannels{end+1}=listChannels{1};
            newname='newchannel';

            results=myDialog({'Positions','ChannelToReplace','NewChannelName'},{deffov,listChannels,newname},...
                'Tip',{'Choose the list of positions to be processed','Choose the channel to be replaced','Enter the new name'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Change the name of a channel');

            if numel(results)==0
                return;
            end


            chan=results.ChannelToReplace{end};


            newchan=results.NewChannelName;

            for i=str2num(results.Positions)

                disp([ 'Processing position ' num2str(i) 'out of ' results.Positions]);
                for j=1:numel(1:numel(shallowObj.fov(i).roi))

                    pix=shallowObj.fov(i).roi(j).findChannelID(chan);

                    if numel(pix)
                        shallowObj.fov(i).roi(j).display.channel{pix}=newchan;
                        disp('Change channel name is done !')
                    else
                        disp(['Channel ' chan ' not found for ROI ' shallowObj.fov(i).roi(j).id ]);
                    end
                end
            end


        end

        % Callback function
        function ChangechannelnameMenuSelected(app, event)

            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            selectedNodes=app.Tree.SelectedNodes;

            okclas=0;
            if strcmp(selectedNodes.Tag,'Projectclassi')
                okclas=1;
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
            end

            if strcmp(selectedNodes.Tag,'Projectpipeline')
                app.ProjectsPanel.Title='Pipeline run';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Pipeline Run...';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                runObj=shallowObj.processing.pipeline(pos);

                t='';
                t=[t 'Run path: ' newline];
                t=[t runObj.path newline newline];

                if isprop(runObj,'templateId') && ~isempty(runObj.templateId)
                    t=[t 'Template: ' char(string(runObj.templateId)) newline];
                end
                if isprop(runObj,'status') && ~isempty(runObj.status)
                    t=[t 'Status: ' char(string(runObj.status)) newline];
                end
                if isprop(runObj,'runId') && ~isempty(runObj.runId)
                    t=[t 'Run ID: ' char(string(runObj.runId)) newline];
                end

                app.ProjectInformationLabel.Text=t;
            end

            if strcmp(selectedNodes.Tag,'Pipeline')
                app.ProjectsPanel.Title='Pipeline';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Pipeline...';

                cc=app.Tree.SelectedNodes.UserData;
                varName=app.Data.Pipeline{cc};
                pipeObj=evalin('base',varName);

                t='';
                t=[t 'Pipeline path: ' newline];
                t=[t pipeObj.path newline newline];

                if numel(pipeObj.description)
                    t=[t 'Description: ' char(string(pipeObj.description)) newline];
                end
                if numel(pipeObj.version)
                    t=[t 'Version: ' char(string(pipeObj.version)) newline];
                end
                if isprop(pipeObj,'nodes')
                    t=[t 'Nodes: ' num2str(numel(pipeObj.nodes)) newline];
                end

                app.ProjectInformationLabel.Text=t;
            end

            if strcmp(selectedNodes.Tag,'Classifier')
                okclas=1;
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{cc};

                clas=evalin('base',proj);
            end

            if okclas==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            defroi=['1:'  num2str(numel(clas.roi)) ];

            listChannels=listAvailableChannels
            listChannels{end+1}=listChannels{1};
            newname='newchannel';

            results=myDialog({'ROIs','ChannelToReplace','NewChannelName'},{defroi,listChannels,newname},...
                'Tip',{'Choose the ROIs to replace','Choose the channel to be replaced','Enter the new name'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Change the name of a channel');

            if numel(results)==0
                return;
            end


            chan=results.ChannelToReplace{end};


            newchan=results.NewChannelName;

            rois=str2num(results.ROIs);

            for j=rois %1:numel(1:numel(clas.roi))

                pix=clas.roi(j).findChannelID(chan);

                if numel(pix)
                    clas.roi(j).display.channel{pix}=newchan;
                    disp(['Change channel name is done for roi ' clas.roi(j).id '!'])
                else
                    disp(['Channel ' chan ' not found for ROI ' clas.roi(j).id ]);
                end
            end


        end

        % Menu selected function: DeleteROIsMenu_2
        function DeleteROIsMenu_2Selected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            selectedNodes=app.Tree.SelectedNodes;
            str=app.Tree.SelectedNodes.Tag;

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Classifier') && ~strcmp(app.Tree.SelectedNodes.Tag,'Projectclassi')
                uialert(app.DetecDivUIFigure,'The selected node is not a classifier !','Error');
                return;
            end

            arg=selectedNodes.UserData;
            cc=arg(1);

            if strcmp(str,'Classifier')
                clas=app.Data.Classifier{cc};
                clas=evalin('base',clas);
            end
            if strcmp(str,'Projectclassi')
                proj=app.Data.Project{cc(1)};
                pos=arg(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
            end


            defroi=['1:'  num2str(numel(clas.roi)) ];
            prompt = {'ROIs that will be deleted:'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Deleting ROIs';

            dims = [1 100];


            definput = {defroi};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                return;
            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase selected ROIs; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(answer{1});
                if numel(id)
                    pix=setxor(1:numel(clas.roi),id);

                    if numel(pix)==0
                        clas.roi=roi;
                        clas.channelName={};
                    else
                        clas.roi=clas.roi(pix);
                    end

                end

                RefreshtreewindowMenuSelected(app)
            end


        end

        % Menu selected function: ClassifyDataMenu
        function ClassifyDataMenuSelected(app, event)
            classifyDataGUI;
        end

        % Menu selected function: RestoredeletedROIsMenu
        function RestoredeletedROIsMenuSelected(app, event)
            % in case rois have been redfined, but are still stored on the
            % hard drive, this function allows one to restore the previous
            % verison

            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            str={};
            for i=1:numel(shallowObj.fov)
                str{i}=[shallowObj.fov(i).id];
            end

            str{end+1}=[shallowObj.fov(1).id];

            results=myDialog({'Position_to_restore'},...
                {str},...
                'Tip',{'Choose the position in which the ROIs should be estored'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose ROI extraction parameters');

            if numel(results)==0
                return;
            end

            posname=results.Position_to_restore{end};
            pix = find(matches(str(1:end-1),posname));

            dirpath=fullfile(shallowObj.io.path,[shallowObj.io.file '/' str{pix}]);

            ls=dir(dirpath);
            lsname={ls.name};

            for i=1:numel(shallowObj.fov(pix).roi)

                nme=['im_' shallowObj.fov(pix).roi(i).id '.mat'];

                if numel(find(matches(lsname,nme)))
                    fle=fullfile(dirpath,nme);
                    tmp=load(fle);

                    shallowObj.fov(pix).roi(i)=tmp.roiobj;

                    %   shallowObj.fov(pix).roi(i).clear;
                    %   shallowObj.fov(pix).roi(i).save('results');
                    shallowObj.fov(pix).roi(i).save;
                    shallowObj.fov(pix).roi(i).clear;
                end

            end

            % for i=results.Position_to


        end

        % Menu selected function: ExportdataMenu
        function ExportdataMenuSelected(app, event)
            %     exportdata;
            detector;
        end

        % Menu selected function: ConvertoldtrainingandresultdataMenu
        function ConvertoldtrainingandresultdataMenuSelected(app, event)

            app.Data.st=gatherVariablesFromWorkspace;

            s=app.Data.st;


            % list avaialable fovs and classi with ROIs


            list=[];

            for i=1:numel(s.Project)

                proj=s.Project{i};

                for k=1:numel(s.Projectpos{i})

                    tmp= evalin('base',[proj '.fov(' num2str(k) ')']);

                    for j=1:numel(tmp.roi)
                        list=[list tmp.roi(j)];
                    end

                end

                for k=1:numel(s.Projectclassi{i})

                    tmp= evalin('base',[proj '.processing.classification(' num2str(k) ')']);

                    for j=1:numel(tmp.roi)
                        list=[list tmp.roi(j)];
                    end


                end
            end
            for i=1:numel(s.Classifier)

                clas=s.Classifier{i};

                tmp= evalin('base',clas);

                for j=1:numel(tmp.roi)
                    list=[list tmp.roi(j)];
                end


            end

            formatInDataSeries(list);

        end

        % Menu selected function: ProcessdataMenu
        function ProcessdataMenuSelected(app, event)
            processDataGUI;
        end

        % Menu selected function: MakeROImoviesMenu
        function MakeROImoviesMenuSelected(app, event)
            movieGUI;
        end

        % Menu selected function: RestoredeletedROIsMenu_2
        function RestoredeletedROIsMenu_2Selected(app, event)
            % in case rois have been redfined, but are still stored on the
            % hard drive, this function allows one to restore the previous
            % verison

            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            selectedNodes=app.Tree.SelectedNodes;
            str=app.Tree.SelectedNodes.Tag;

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Classifier') && ~strcmp(app.Tree.SelectedNodes.Tag,'Projectclassi')
                uialert(app.DetecDivUIFigure,'The selected node is not a classifier !','Error');
                return;
            end

            arg=selectedNodes.UserData;
            cc=arg(1);

            if strcmp(str,'Classifier')
                clas=app.Data.Classifier{cc};
                clas=evalin('base',clas);
            end

            if strcmp(str,'Projectclassi')
                proj=app.Data.Project{cc(1)};
                pos=arg(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
            end

            dirpath=fullfile(clas.path);

            ls=dir(dirpath);
            lsname={ls.name};

            for i=1:numel(clas.roi)

                nme=['im_' clas.roi(i).id '.mat'];

                if numel(find(matches(lsname,nme)))
                    fle=fullfile(dirpath,nme);
                    tmp=load(fle);
                    clas.roi(i)=tmp.roiobj;
                    %  clas.roi(i).clear;
                    clas.roi(i).save;
                    clas.roi(i).clear;
                end

            end
        end

        % Menu selected function: DeleteclassifierMenu
        function DeleteclassifierMenuSelected(app, event)

        end

        % Menu selected function: AdjustROIsMenu_2
        function AdjustROIsMenu_2Selected(app, event)
            ROIAdjustGUI();
        end

        % Menu selected function: AdjustROIsMenu_3
        function AdjustROIsMenu_3Selected(app, event)
            ROIAdjustGUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DetecDivUIFigure = uifigure('Visible', 'off');
            app.DetecDivUIFigure.Name = 'DetecDiv';
            app.DetecDivUIFigure.Icon = 'detecDiv_logo.png';
            app.FileMenu = uimenu(app.DetecDivUIFigure);
            app.FileMenu.Text = 'File';
            app.NewprojectMenu = uimenu(app.FileMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Text = 'New project...';
            app.OpenprojectMenu = uimenu(app.FileMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project...';
            app.OpenrecentMenu = uimenu(app.FileMenu);
            app.OpenrecentMenu.Text = 'Open recent';
            app.Item1Menu = uimenu(app.OpenrecentMenu);
            app.Item1Menu.Text = 'Item1';
            app.SaveselectedprojectMenu = uimenu(app.FileMenu);
            app.SaveselectedprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveselectedprojectMenuSelected, true);
            app.SaveselectedprojectMenu.Text = 'Save selected project';
            app.Closeproject = uimenu(app.FileMenu);
            app.Closeproject.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectMenuSelected, true);
            app.Closeproject.Text = 'Close selected project';
            app.ExportprojecttoPhylocellMenu = uimenu(app.FileMenu);
            app.ExportprojecttoPhylocellMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportprojecttoPhylocellMenuSelected, true);
            app.ExportprojecttoPhylocellMenu.Separator = 'on';
            app.ExportprojecttoPhylocellMenu.Text = 'Export project to Phylocell...';
            app.NewprojectindependentclassiiferMenu = uimenu(app.FileMenu);
            app.NewprojectindependentclassiiferMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectindependentclassiiferMenuSelected, true);
            app.NewprojectindependentclassiiferMenu.Separator = 'on';
            app.NewprojectindependentclassiiferMenu.Text = 'New project-independent classiifer...';
            app.OpenprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.OpenprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectindependentclassifierMenuSelected, true);
            app.OpenprojectindependentclassifierMenu.Text = 'Open project-independent classifier...';
            app.OpenrecentClassiMenu = uimenu(app.FileMenu);
            app.OpenrecentClassiMenu.Text = 'Open recent independent classifier';
            app.SaveprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.SaveprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectindependentclassifierMenuSelected, true);
            app.SaveprojectindependentclassifierMenu.Text = 'Save project-independent classifier';
            app.CloseprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.CloseprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectindependentclassifierMenuSelected, true);
            app.CloseprojectindependentclassifierMenu.Text = 'Close project-independent classifier';
            app.NewpipelineMenu = uimenu(app.FileMenu);
            app.NewpipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @NewpipelineMenuSelected, true);
            app.NewpipelineMenu.Separator = 'on';
            app.NewpipelineMenu.Text = 'New pipeline...';
            app.OpenpipelineMenu = uimenu(app.FileMenu);
            app.OpenpipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenpipelineMenuSelected, true);
            app.OpenpipelineMenu.Text = 'Open pipeline...';
            app.SavepipelineMenu = uimenu(app.FileMenu);
            app.SavepipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @SavepipelineMenuSelected, true);
            app.SavepipelineMenu.Text = 'Save selected pipeline';
            app.ClosepipelineMenu = uimenu(app.FileMenu);
            app.ClosepipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosepipelineMenuSelected, true);
            app.ClosepipelineMenu.Text = 'Close selected pipeline';
            app.ClassifierRepositoryMenu = uimenu(app.FileMenu);
            app.ClassifierRepositoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassifierRepositoryMenuSelected, true);
            app.ClassifierRepositoryMenu.Separator = 'on';
            app.ClassifierRepositoryMenu.Text = 'Classifier Repository...';
            app.ConvertoldtrainingandresultdataMenu = uimenu(app.FileMenu);
            app.ConvertoldtrainingandresultdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertoldtrainingandresultdataMenuSelected, true);
            app.ConvertoldtrainingandresultdataMenu.Separator = 'on';
            app.ConvertoldtrainingandresultdataMenu.Text = 'Convert old training and result data';
            app.FunctionsMenu = uimenu(app.DetecDivUIFigure);
            app.FunctionsMenu.Text = 'Functions';
            app.ClassifyDataMenu = uimenu(app.FunctionsMenu);
            app.ClassifyDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassifyDataMenuSelected, true);
            app.ClassifyDataMenu.Text = 'Classify Data...';
            app.ProcessdataMenu = uimenu(app.FunctionsMenu);
            app.ProcessdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessdataMenuSelected, true);
            app.ProcessdataMenu.Text = 'Process data...';
            app.ExportdataMenu = uimenu(app.FunctionsMenu);
            app.ExportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportdataMenuSelected, true);
            app.ExportdataMenu.Text = 'Export data...';
            app.MakeROImoviesMenu = uimenu(app.FunctionsMenu);
            app.MakeROImoviesMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeROImoviesMenuSelected, true);
            app.MakeROImoviesMenu.Text = 'Make ROI movies...';
            app.ProjectMenu = uimenu(app.DetecDivUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.PositionsMenu = uimenu(app.ProjectMenu);
            app.PositionsMenu.Text = 'Positions';
            app.AddPositionsDataMenu = uimenu(app.PositionsMenu);
            app.AddPositionsDataMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPositionsDataMenuSelected, true);
            app.AddPositionsDataMenu.Text = 'Add Positions (Data)...';
            app.SetFrameOrientationMenu = uimenu(app.PositionsMenu);
            app.SetFrameOrientationMenu.MenuSelectedFcn = createCallbackFcn(app, @SetFrameOrientationMenuSelected, true);
            app.SetFrameOrientationMenu.Separator = 'on';
            app.SetFrameOrientationMenu.Text = 'Set Frame Orientation...';
            app.CreateFullFrameROisMenu = uimenu(app.PositionsMenu);
            app.CreateFullFrameROisMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateFullFrameROisMenuSelected, true);
            app.CreateFullFrameROisMenu.Text = 'Create Full Frame ROis...';
            app.DeleteROIsMenu = uimenu(app.PositionsMenu);
            app.DeleteROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteROIsMenuSelected, true);
            app.DeleteROIsMenu.Text = 'Delete ROIs...';
            app.AdjustROIsMenu = uimenu(app.PositionsMenu);
            app.AdjustROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustROIsMenuSelected, true);
            app.AdjustROIsMenu.Text = 'Adjust ROIs...';
            app.DeletePositionsMenu_2 = uimenu(app.PositionsMenu);
            app.DeletePositionsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DeletePositionsMenu_2Selected, true);
            app.DeletePositionsMenu_2.Text = 'Delete Positions...';
            app.RestoredeletedROIsMenu = uimenu(app.PositionsMenu);
            app.RestoredeletedROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoredeletedROIsMenuSelected, true);
            app.RestoredeletedROIsMenu.Text = 'Restore deleted ROIs...';
            app.ClassifiersMenu = uimenu(app.ProjectMenu);
            app.ClassifiersMenu.Text = 'Classifiers';
            app.AddclassifierMenu = uimenu(app.ClassifiersMenu);
            app.AddclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @AddclassifierMenuSelected, true);
            app.AddclassifierMenu.Text = 'Add classifier...';
            app.DeleteclassifierMenu = uimenu(app.ClassifiersMenu);
            app.DeleteclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteclassifierMenuSelected, true);
            app.DeleteclassifierMenu.Text = 'Delete classifier...';
            app.AdjustROIsMenu_3 = uimenu(app.ClassifiersMenu);
            app.AdjustROIsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @AdjustROIsMenu_3Selected, true);
            app.AdjustROIsMenu_3.Text = 'Adjust ROIs...';
            app.DeleteROIsMenu_2 = uimenu(app.ClassifiersMenu);
            app.DeleteROIsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DeleteROIsMenu_2Selected, true);
            app.DeleteROIsMenu_2.Text = 'Delete ROIs...';
            app.RestoredeletedROIsMenu_2 = uimenu(app.ClassifiersMenu);
            app.RestoredeletedROIsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RestoredeletedROIsMenu_2Selected, true);
            app.RestoredeletedROIsMenu_2.Text = 'Restore deleted ROIs...';
            app.AdjustROIsMenu_2 = uimenu(app.ProjectMenu);
            app.AdjustROIsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AdjustROIsMenu_2Selected, true);
            app.AdjustROIsMenu_2.Text = 'Adjust ROIs...';
            app.DisplayMenu = uimenu(app.DetecDivUIFigure);
            app.DisplayMenu.Text = 'Display';
            app.RefreshtreewindowMenu = uimenu(app.DisplayMenu);
            app.RefreshtreewindowMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshtreewindowMenuSelected, true);
            app.RefreshtreewindowMenu.Text = 'Refresh tree window';
            app.SettingsMenu = uimenu(app.DetecDivUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.UserpreferencesMenu = uimenu(app.SettingsMenu);
            app.UserpreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @UserpreferencesMenuSelected, true);
            app.UserpreferencesMenu.Text = 'User preferences...';
            app.Tree = uitree(app.DetecDivUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'Use left-click to scroll projects and classifiers; Use right-click to open positions and classifiers'};
            app.ProjectsNode = uitreenode(app.Tree);
            app.ProjectsNode.Text = 'Projects';
            app.IndependentClassifiersNode = uitreenode(app.Tree);
            app.IndependentClassifiersNode.Text = 'Independent Classifiers';
            app.IndependentPipelinesNode = uitreenode(app.Tree);
            app.IndependentPipelinesNode.Text = 'Independent Pipelines';
            app.ProjectsPanel = uipanel(app.DetecDivUIFigure);
            app.ProjectsPanel.Title = 'Projects';
            app.UIAxes = uiaxes(app.ProjectsPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.AdddataButton = uibutton(app.ProjectsPanel, 'push');
            app.AdddataButton.ButtonPushedFcn = createCallbackFcn(app, @AdddataButtonPushed, true);
            app.AdddataButton.Icon = 'data.png';
            app.AdddataButton.Visible = 'off';
            app.AdddataButton.Tooltip = {'Link directory containing images to the project'};
            app.AdddataButton.Text = 'Add data...';
            app.AddclassifierButton = uibutton(app.ProjectsPanel, 'push');
            app.AddclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @AddclassifierButtonPushed, true);
            app.AddclassifierButton.Icon = 'brain.png';
            app.AddclassifierButton.Visible = 'off';
            app.AddclassifierButton.Tooltip = {'Creates a new classifier or imports an existing classifier to the project'};
            app.AddclassifierButton.Text = 'Add classifier...';
            app.ProjectInformationLabel = uilabel(app.ProjectsPanel);
            app.ProjectInformationLabel.Text = 'Project Information';
            app.IdentifyROIsinpositionsButton = uibutton(app.ProjectsPanel, 'push');
            app.IdentifyROIsinpositionsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyROIsinpositionsButtonPushed, true);
            app.IdentifyROIsinpositionsButton.Visible = 'off';
            app.IdentifyROIsinpositionsButton.Tooltip = {'This allows you to use a pattern to identify multiple ROIs automatically. The pattern must be defined in one of the positions'};
            app.IdentifyROIsinpositionsButton.Text = 'Identify ROIs in positions...';
            app.ExtractROIhypervolumesButton = uibutton(app.ProjectsPanel, 'push');
            app.ExtractROIhypervolumesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractROIhypervolumesButtonPushed, true);
            app.ExtractROIhypervolumesButton.Visible = 'off';
            app.ExtractROIhypervolumesButton.Tooltip = {'Generates the ROIs by extracting subimages from raw data and copy them in the project directory. After this step, access to the raw data is no longer necessary'};
            app.ExtractROIhypervolumesButton.Text = 'Extract ROI hypervolumes...';
            app.UpdaterawdatapathButton = uibutton(app.ProjectsPanel, 'push');
            app.UpdaterawdatapathButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterawdatapathButtonPushed, true);
            app.UpdaterawdatapathButton.Visible = 'off';
            app.UpdaterawdatapathButton.Tooltip = {'Please select the directory that contains  a list of images in your project'};
            app.UpdaterawdatapathButton.Text = 'Update raw data path...';
            app.ClassifydataButton = uibutton(app.ProjectsPanel, 'push');
            app.ClassifydataButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifydataButtonPushed, true);
            app.ClassifydataButton.Icon = 'gears.png';
            app.ClassifydataButton.Visible = 'off';
            app.ClassifydataButton.Tooltip = {'Classifies defined ROIs using a given classifier '};
            app.ClassifydataButton.Text = 'Classify data...';
            app.AddprocessorButton = uibutton(app.ProjectsPanel, 'push');
            app.AddprocessorButton.ButtonPushedFcn = createCallbackFcn(app, @AddprocessorButtonPushed, true);
            app.AddprocessorButton.Icon = 'processor.png';
            app.AddprocessorButton.Visible = 'off';
            app.AddprocessorButton.Text = 'Add processor...';
            app.ProcessdataButton = uibutton(app.ProjectsPanel, 'push');
            app.ProcessdataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessdataButtonPushed, true);
            app.ProcessdataButton.Icon = 'gears.png';
            app.ProcessdataButton.Visible = 'off';
            app.ProcessdataButton.Text = 'Process data...';
            app.OpenButton = uibutton(app.ProjectsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Visible = 'off';
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.DetecDivUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detecdiv

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DetecDivUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DetecDivUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DetecDivUIFigure)
        end
    end
end