classdef detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DivisioncountingandRLSanalysisUIFigure  matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoaddatagroupsMenu           matlab.ui.container.Menu
        SavedatagroupsasMenu         matlab.ui.container.Menu
        PlotexportresultsLabel       matlab.ui.control.Label
        ComputedivisionsandRLSLabel  matlab.ui.control.Label
        SelectgroupsofdataLabel      matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel       matlab.ui.control.Label
        PlotDivExportButton_2        matlab.ui.control.Button
        SelecttableindicesEditField  matlab.ui.control.EditField
        SelecttableindicesEditFieldLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        ExtractdivisionsRLSButton    matlab.ui.control.Button
        DataselectionPanel           matlab.ui.container.Panel
        DivisionextractionparametersPanel  matlab.ui.container.Panel
        DatagroupsPanel              matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        SaveselectedgroupButton      matlab.ui.control.Button
        AssignselectedgroupparamtoallButton  matlab.ui.control.Button
        RenamegroupEditField         matlab.ui.control.EditField
        RenamegroupEditFieldLabel    matlab.ui.control.Label
        DeleteselectedgroupButton    matlab.ui.control.Button
        NewgroupButton               matlab.ui.control.Button
        DatagroupsListBox            matlab.ui.control.ListBox
        TabGroup                     matlab.ui.container.TabGroup
        DivisionplotTab              matlab.ui.container.Tab
        PlotDivExportButton          matlab.ui.control.Button
        divParametersPanel           matlab.ui.container.Panel
        RLSplotTab                   matlab.ui.container.Tab
        PlotRLSExportButton          matlab.ui.control.Button
        RLSParametersPanel           matlab.ui.container.Panel
        SignalplotTab                matlab.ui.container.Tab
        PlotSignalExportButton       matlab.ui.control.Button
        SignalParametersPanel        matlab.ui.container.Panel
    end


    properties (Access = private)
        Data % Description
    end

    methods (Access = private)
        function  gatherVarsFromWorkspace(app)

            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            stclassi=struct('Classifier',{[]},'Projectclassi',{[]});

            cc=0;
            cd=0;
            app.Data.st=[];
            app.Data.stclassi=[];

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});

                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};
                    tmpclassi2=classi;

                    for k=1:numel(tmp.processing.classification)
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];

                        tmpclassi2(k)= tmp.processing.classification(k);
                    end


                    st.Projectclassi{cc}=tmpclassi;
                    stclassi.Projectclassi{cc}=tmpclassi2;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        tmpproj = [tmpproj tmp.fov(k).id];
                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')

                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};

                    stclassi.Classifier{cd}=evalin('base',varlist{i});
                    % aa= st.Classifier{cd}
                end

            end

            %  st
            app.Data.st=st;
            app.Data.stclassi=stclassi;
            %    app.Data.convert={};
        end

        function divparam= newDivPlot(app)
            tip={'Set filename to be written in the current directory',...
                'Set the frame interval (min)',...
                'Set the last birth event (frames)',...
                'Set the bin size (minutes)',...
                'Set the max bin for the histogram (minutes)'};

            divparam=struct( 'Filename','myDivfile',...
                'FrameInterval',5,...
                'maxBirth',100,...
                'binSize',5,...
                'maxBin',200,...
                'tip',{tip});
            %       'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...

        end

        function param = newGroup(app)
            tip={'Select the classifier used to score divisions',...
                'Choose the type of division classification (bud or div) ',...
                'Perform postprocessing or not',...
                'Detect errors or not',...
                'Lifespan arrest threshold( frames)',...
                'Number of consecutive dead frames before death is defined as the end of the RLS',...
                'Number of consecutive clog frames before clog is defined as the end of the RLS',...
                'Number of frames before traps is declared empty',...
                '?',...
                'Frames array used for analysis (set -1 if all frames should be considered)',...
                'Check this box if this group should be plotted',...
                'Check this box if predictions and GT should be compared'};
            varlist=app.Data.st;
            varlistclassi=app.Data.stclassi;
            classilist={};
            % classiobj=classi;
            cc=1;
            classiobj={};


            for i=1:numel(varlist.Projectclassi)
                for j=1:numel(varlist.Projectclassi{i})
                    classilist{cc}=varlist.Projectclassi{i}{j};
                    tmp=varlistclassi.Projectclassi{i}
                    classiobj{cc}=tmp(j);
                    %  tmp=varlist.Projectclassi{i}{j}
                    cc=cc+1;
                end
            end

            for i=1:numel(varlist.Classifier)
                classilist{cc}=varlist.Classifier{i};
                classiobj{cc}=varlistclassi.Classifier{i};
                cc=cc+1;
            end


            app.Data.classiobj=classiobj;
            if numel(classilist)
            classilist{end+1}=classilist{1};
            else
             classilist={'No Classi in this project','No Classi in this project'}   ;
            end

            param=struct('classifierName',{classilist},...
                'classiftype',{{'bud','div','bud'}},...
                'postProcessing',true,...
                'errorDetection',true,...
                'ArrestThreshold',175,...
                'DeathThreshold',3,...
                'ClogThreshold',1,...
                'EmptyThresholdDiscard',500,...
                'EmptyThresholdNext',100,...
                'Frames',[-1],...
                'PlotThisGroup',true,...
                'PredVSGT',false,...
                'tip',{tip});
            %   'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...
        end

        function divparam = newRLSPlot(app)
            tip={'Set filename to be written in the current directory',...
                'Plot 95% confidence interval for survival',...
                'Plot Hazard rate',...
                'If plot hazard rate, which binning to use (higher binning decreases noise but reduces temporal resolution)',...
                'If plot hazard rate, stop displaying the curve when less than this number of cells are still alive (avoid interpretations based on very few cells). !!For the moment, only works if bootstrap is ticked!!',...
                'Set the frame interval (min)',...
                'Set the last birth event (frames)',...
                'Check to bootstrap data (error estimate)',...
                'Number of boostrap steps'};

            divparam=struct('Filename','myRLSfile',...
                'ConfidenceInterval',true,...
                'HazardRate',false,...
                'Binning_HazardRate',4,...
                'MinNumberCell_HazardRate',10,...
                'FrameInterval',5,...
                'maxBirth',100,...
                'Bootstrap',false,...
                'Bootstrap_Steps',200,...
                'tip',{tip});
            %       'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...
        end
        
        function signalparam = newSignalPlot(app)
              tip={'Set filename to be written in the current directory',...
                'Choose whether data use generation or physical time units',...
                'Set the frame interval (min)',...
                'Set the last birth event (frames)',...
                'Check to bootstrap data (error estimate)',...
                'Number of boostrap steps',...
                'Align trajectories from birth, SEP, or death'};

            signalparam=struct('Filename','mySignalfile',...
                'timeOrGen',{{'Time','Generation','Time'}},...
                'FrameInterval',5,...
                'maxBirth',100,...
                'minDiv',1,...
                'minSep',0,...
                'Align_Traj',{{'birth','SEP','death','birth'}},...
                'tip',{tip});
        end
    end


    methods (Access = public)
        function display2(app,datapicker)

            d = uiprogressdlg(app.DivisioncountingandRLSanalysisUIFigure,'Title','Please Wait...',...
                'Message','Loading data group...');
            d.Value=0.33;

            groups = app.Data.groups;
            items= {groups.Name};

            if numel(app.DatagroupsListBox.Value)
                aa=app.DatagroupsListBox.Value;
                pix=find(matches(items,aa));

                if numel(pix)==0
                    pix=1;
                else
                    pix=pix(1);
                end
            else
                pix=1;
            end

            app.DatagroupsListBox.Items=items;
            app.DatagroupsListBox.Value=items{pix};

            %                    app.Data.selectedgroup=pix;
            %                    pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            param=app.Data.groups(pix).Param;

            %here
            
            tip=param.tip;
            param=rmfield(param,'tip');
            app.DivisionextractionparametersPanel.UserData=[];
            delete(app.DivisionextractionparametersPanel.Children);
            struct2GUI(param,[10 330],'Handle',app.DivisionextractionparametersPanel,'Tip',tip,'Flag',app.Lamp);
            app.DivisionextractionparametersPanel.UserData.tip=tip;
            app.RenamegroupEditField.Value=aa;

            if nargin==2 % creates datapicker
                if numel(app.Data.groups(pix).Data.nodeid)
                    nodes=app.Data.groups(pix).Data;
                    nodes=app.Data.groups(pix).Data.nodeid;
                else
                    nodes=[];
                end
                %  no=app.Data.groups(pix).Data.filepath{1}


                if isa('datatree','var')
                    if ishandle(datatree)
                        delete(datatree)
                    end
                end
                d.Value=0.5;
                d.Message='Refreshing data picker...';

                [datatree,~]=datapickerGUI2('Handle',app.DataselectionPanel,'Flag',app.Lamp,'Position',[12 12 240 350],'Input',nodes);
                app.Data.tree=datatree;
                %  tmp=app.Data.tree.UserData
                drawnow


            end

            % display rls table if avaialable

            t=app.UITable;
            t.ColumnWidth={50, 100,  'auto', 50,50,70,35,50,50};
            t.ColumnEditable=[true false false false false false false false];

            if isfield(app.Data.groups(pix).Data,'rls')
                if numel(app.Data.groups(pix).Data.rls)

                    rls=app.Data.groups(pix).Data.rls;
                    if numel(app.Data.groups(pix).Table)
                        t.Data=app.Data.groups(pix).Table;
                   %     'ok'
                    else


                        Data=t.Data;

                        for i=1:numel(rls)
                            Data{i,1}=true;
                            Data{i,2}=rls(i).name;
                            Data{i,3}=num2str(rls(i).divDuration);
                            Data{i,4}=rls(i).ndiv;
                            Data{i,5}=rls(i).sep;
                            Data{i,6}=rls(i).endType;
                            Data{i,7}=logical(rls(i).groundtruth);
                            Data{i,8}=rls(i).frameBirth;
                            Data{i,9}=rls(i).frameEnd;
                        end
                        Data=Data(1:numel(rls),:);
                        t.Data=Data;
                        app.Data.groups(pix).Table=Data;
                    end
                    app.SelecttableindicesEditField.Value=['1:' num2str(numel(rls))];
                end
            end

            d.Value=0.9;
            d.Message='Data loaded...';
            close(d)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data=[];

            gatherVarsFromWorkspace(app)

            param = newGroup(app);
            app.Data.groups=struct('Name','group01','Data',[],'Param',param,'Table',[]);
            app.Data.groups.Data.nodeid=[];
            app.Data.groups.Data.roiobj=[];

            display2(app,'datapicker')

            app.Data.divparam=   newDivPlot(app);
            param=app.Data.divparam;
            tip=param.tip;
            param=rmfield(param,'tip');
            struct2GUI(param,[10 210],'Handle',app.divParametersPanel,'Tip',tip);

            app.Data.rlsparam=   newRLSPlot(app);
            param=app.Data.rlsparam;
            tip=param.tip;
            param=rmfield(param,'tip');
            struct2GUI(param,[10 210],'Handle',app.RLSParametersPanel,'Tip',tip);

            app.Data.signalparam=   newSignalPlot(app);
            param=app.Data.signalparam;
            tip=param.tip;
            param=rmfield(param,'tip');
            struct2GUI(param,[10 210],'Handle',app.SignalParametersPanel,'Tip',tip);

        end

        % Value changed function: DatagroupsListBox
        function DatagroupsListBoxValueChanged(app, event)
            tmp=app.DatagroupsListBox.Value;
            app.Data.selectedgroup = tmp;
            display2(app,'refresh')
        end

        % Value changed function: RenamegroupEditField
        function RenamegroupEditFieldValueChanged(app, event)
            value = app.RenamegroupEditField.Value;

            if numel(app.DatagroupsListBox.Value)
                pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
                pix=pix(1);
                app.Data.groups(pix).Name=value;
                app.DatagroupsListBox.Items{pix}=value;
                app.DatagroupsListBox.Value=value;
            end
        end

        % Button pushed function: NewgroupButton
        function NewgroupButtonPushed(app, event)
            pix=numel(app.DatagroupsListBox.Items);

            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            % take param value from selected position
            % here
            if numel(sel)
                param= app.Data.groups(sel).Param;
            else
                param = newGroup(app);

            end

            app.DatagroupsListBox.Items=[app.DatagroupsListBox.Items {['group' num2str(pix+1)]}];
            app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{end};

            %  aa= size(app.Data.groups)

            app.Data.groups(pix+1)=app.Data.groups(pix);

            app.Data.groups(pix+1).Name=app.DatagroupsListBox.Value;
            app.Data.groups(pix+1).Param=param;
            display2(app)


        end

        % Button pushed function: DeleteselectedgroupButton
        function DeleteselectedgroupButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                app.DatagroupsListBox.Items=app.DatagroupsListBox.Items(li);
                app.Data.groups= app.Data.groups(li);
                app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{1};
            end
            display2(app)
        end

        % Button pushed function: SaveselectedgroupButton
        function SaveselectedgroupButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                app.Data.groups(sel).Param=app.DivisionextractionparametersPanel.UserData;

                if numel(app.Data.tree.UserData)

                    app.Data.groups(sel).Data.nodeid= app.Data.tree.UserData.nodeid;
                    app.Data.groups(sel).Data.roiobj= app.Data.tree.UserData.roiobj;
                    app.Data.groups(sel).Data.filepath= app.Data.tree.UserData.filepath;
                    app.Data.groups(sel).Data.type= app.Data.tree.UserData.type;
                end
                app.Lamp.Color=[0 1 0];
            end
        end

        % Button pushed function: AssignselectedgroupparamtoallButton
        function AssignselectedgroupparamtoallButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                if numel(li)
                    for i=li
                        app.Data.groups(i).Param=app.Data.groups(sel).Param;
                        app.Data.groups(i).Data=app.Data.groups(sel).Data;
                    end
                end
            else
                uialert(app.DivisioncountingandRLSanalysisUIFigure,'First select a data group !','Warning');
            end

        end

        % Menu selected function: LoaddatagroupsMenu
        function LoaddatagroupsMenuSelected(app, event)

            [file,path] = uigetfile('*.mat','Select a shallow project',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                load(filename);
                app.Data.groups=groups;

                % check if projects are loaded ; if not , load them ...
                for i=1:numel(groups)
                    %  tmp=groups(i).Data
                    for j=1:numel(groups(i).Data.filepath)
                        if numel(groups(i).Data.filepath{j})
                            if strcmp(groups(i).Data.type{j},'shallow')
                                sha=shallowLoad(groups(i).Data.filepath{j});
                                if numel(sha)
                                    name=sha.io.file;
                                    assignin('base',name,sha);
                                end

                            end
                            if strcmp(groups(i).Data.type{j},'classi')

                                sha=classiLoad(groups(i).Data.filepath{j});
                                if numel(sha)
                                    name=[sha.strid '_indep'];
                                    assignin('base',name,sha);
                                end
                            end


                        end
                    end
                end

                display2(app,'refresh');
            end

        end

        % Menu selected function: SavedatagroupsasMenu
        function SavedatagroupsasMenuSelected(app, event)
            [file,path] = uiputfile('*.mat','Choose a data group filename',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                groups=app.Data.groups;
                save(filename, 'groups');
            end
        end

        % Callback function
        function TabGroupSelectionChanged(app, event)


        end

        % Button pushed function: PlotDivExportButton
        function PlotDivExportButtonPushed(app, event)
            divParam=   app.divParametersPanel.UserData;

            arg={};
            %  if divParam.ExportPlot
            %  arg=[arg 'ExportPlot' ];
            %end

            arg=[arg 'FrameInterval', divParam.FrameInterval];
            arg=[arg 'maxBirth', divParam.maxBirth];
            arg=[arg 'binSize', divParam.binSize];
            arg=[arg 'maxBin', divParam.maxBin];

            groups=app.Data.groups;

            cc=1;
            rlstot=[];

            grcc=[];
            groups(1).div=[];

            for i=1:numel(groups)
                data=groups(i).Data;
                param=groups(i).Param ;
                name=groups(i).Name;
                tabl=groups(i).Table; 

                if numel(tabl)==0
                    continue
                end

                if param.PlotThisGroup==true % to plot

%                     claslist=param.classifierName(1:end-1);
%                     sel=param.classifierName{end};
% 
%                     pix=find(matches(claslist,sel));

                    gtpred=param.PredVSGT;

                    if gtpred==true
                        %                 if param.GroundtruthVSPredictions
                        param.comment={'Groundtruth','Prediction'};
                        param.GroundtruthVSPredictions=true;
                    else
                        param.comment={name};
                        param.GroundtruthVSPredictions=false;
                    end
                    %
                    rls=data.rls;
                    sel=find(cellfun(@(x) x==true,tabl(:,1)));
                    rls=rls(sel);

                    %
                    if gtpred==false
                        for j=1:numel(rls)
                            rls(j).condition=cc;
                        end
                        cc=cc+1;
                    end
                    %
                    groups(i).div=rls;
                    %
                    if gtpred
                        %    if tdata{i,1}==true
                        arg2=[arg 'GT'  'Filename' [divParam.Filename '_' name '_predvsgt'] 'figName' name];
                        divt=plotDivtimes(rls,arg2{:});

                    else
                        %      if tdata{i,1}==true % plot
                        rlstot=[rlstot; rls];
                        grcc=[grcc; i];
                        %     end
                    end
                end
            end

            if numel(rlstot)
                arg=[arg 'figName' 'pooled groups' 'Filename' divParam.Filename];
                divt=plotDivtimes(rlstot,arg{:});

            end


        end

        % Button pushed function: PlotRLSExportButton
        function PlotRLSExportButtonPushed(app, event)
            divParam=   app.RLSParametersPanel.UserData;

            arg={};


            if divParam.Bootstrap
                arg=[arg 'Bootstrap' ];
            end


            if divParam.ConfidenceInterval
                arg=[arg 'ConfidenceInterval' ];
            end

            if divParam.HazardRate
                arg=[arg 'HazardRate' ];
            end

            if divParam.Binning_HazardRate
                arg=[arg 'Binning_HazardRate' divParam.Binning_HazardRate];
            end

            if divParam.MinNumberCell_HazardRate
                arg=[arg 'MinNumberCell_HazardRate' divParam.MinNumberCell_HazardRate];
            end

            if divParam.Bootstrap_Steps
                arg=[arg 'Bootstrap_Steps' divParam.Bootstrap_Steps ];
            end

            arg=[arg 'FrameInterval', divParam.FrameInterval];

            arg=[arg 'maxBirth', divParam.maxBirth];


            groups=app.Data.groups;

            cc=1;
            rlstot=[];

            grcc=[];
            groups(1).div=[];


            for i=1:numel(groups)
                data=groups(i).Data;
                param=groups(i).Param ;
                name=groups(i).Name;
                 tabl=groups(i).Table; 

                if numel(tabl)==0
                    continue
                end

                if param.PlotThisGroup==true % to plot

                   % classifstrid=param.classifierName{end};
                   % classif=evalin('base',classifstrid);

                    gtpred=param.PredVSGT;

                    if gtpred==true
                        %                 if param.GroundtruthVSPredictions
                        param.comment={'Groundtruth','Prediction'};
                        param.GroundtruthVSPredictions=true;
                    else
                        param.comment={name};
                        param.GroundtruthVSPredictions=false;
                    end
                    %

                    rls=data.rls;
                    sel=find(cellfun(@(x) x==true,tabl(:,1)));
                    rls=rls(sel);

                    %
                    if gtpred==false
                        for j=1:numel(rls)
                            rls(j).condition=cc;
                        end
                        cc=cc+1;
                    end
                    %
                    groups(i).rls=rls;
                    %
                    if gtpred
                        %    if tdata{i,1}==true
                        arg2=[arg 'GT'  'Filename' [divParam.Filename '_' name '_predvsgt'] 'figName' name];
                        rlst=plotRLS(rls,arg2{:});


                    else

                        rlstot=[rlstot; rls];
                        grcc=[grcc; i];

                    end
                end
            end

            if numel(rlstot)
                arg=[arg 'figName' 'pooled groups' 'Filename' divParam.Filename];
                rlst=plotRLS(rlstot,arg{:});

            end
        end

        % Button pushed function: ExtractdivisionsRLSButton
        function ExtractdivisionsRLSButtonPushed(app, event)
            d = uiprogressdlg(app.DivisioncountingandRLSanalysisUIFigure,'Title','Please Wait...',...
                'Message','Computing divisions & RLS...');
            d.Value=0.33;

            groups=app.Data.groups;

            rlstot=[];

            cc=1;


            for i=1:numel(groups)
                d.Message=['Processing data group ' num2str(i) '/' num2str(numel(groups)) '...'];
                data=groups(i).Data;
                param=groups(i).Param ;
                name=groups(i).Name;

                claslist=param.classifierName(1:end-1);
                sel=param.classifierName{end};

                pix=find(matches(claslist,sel));

                %   aa=app.Data.classiobj
                selclassi=app.Data.classiobj{pix};

                param2=param;
                param2.classifierName=selclassi;


                d.Value=0.33+0.66*(i-1)./double(numel(groups));

                if numel(data.roiobj)==0
                    close(d);
                    uialert(app.DivisioncountingandRLSanalysisUIFigure,'No ROIs were selected in the data selection tab ! Quitting...','Warning');
                    return;
                end

                measureRLS3(data.roiobj,param2);

                gtpred=param.PredVSGT;

                if gtpred==true
                    %                 if param.GroundtruthVSPredictions
                    param.comment={'Groundtruth','Prediction'};
                    param.GroundtruthVSPredictions=true;
                else
                    param.comment={name};
                    param.GroundtruthVSPredictions=false;
                end
                %
                %  classifstrid=param.classifierName{end};
                %  classif=evalin('base',classifstrid);

                rls=createRLSfile(selclassi,{data.roiobj},param);
                groups(i).Data.rls=rls;
                groups(i).Table=[];
            end


            app.Data.groups=groups;
            display2(app)

            assignin('base','groups',groups);

            close(d)
        end

        % Value changed function: SelecttableindicesEditField
        function SelecttableindicesEditFieldValueChanged(app, event)

            value = str2num(app.SelecttableindicesEditField.Value);
            t=app.UITable;
            Data=t.Data;

            value=intersect(1:size(Data,1),value);
            Data(:,1)={false};
            Data(value,1)={true};
            t.Data=Data;
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            t=app.UITable;
            Data=t.Data;


            if selection(2)==2 % select roi name
                sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
                sel=sel(1);

                if numel(sel)
                    data= app.Data.groups(sel).Data;
                    param= app.Data.groups(sel).Param;
                    name=Data{selection(1),2};

                    tmp={data.roiobj.id};

                    pix=find(matches(tmp,name));

                    claslist=param.classifierName(1:end-1);
                    sel=param.classifierName{end};

                    pix2=find(matches(claslist,sel));

                    %   aa=app.Data.classiobj
                    classif=app.Data.classiobj{pix2};

                    %  classifierName=app.Data.groups(sel).Param.classifierName{end};
                    %  classif=evalin('base',classifierName);
                    classifstrid=classif.strid;
                    roisel=data.roiobj(pix);

                    %tmp=roisel.results
                    roisel.traj(classifstrid,'option',2);
                end
            end

        end

        % Button pushed function: PlotDivExportButton_2
        function PlotDivExportButton_2Pushed(app, event)
            % export as excel file

            t=app.UITable;
            Data=t.Data;

            if numel(Data)==0
                return;
            end

            sel=find(cellfun(@(x) x==true,Data(:,1)));

            sel2=find(cellfun(@(x) x~=-1,Data(:,4)));

            sel=intersect(sel,sel2);

            Data=Data(sel,:);
            val=app.FilenameEditField.Value;
            aa= t.ColumnName;
            writecell([aa'; Data],val,'WriteMode','replacefile')
            disp('Export to xls file in the current directory is done')
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            groups = app.Data.groups;
            items= {groups.Name};
            if numel(app.DatagroupsListBox.Value)
                aa=app.DatagroupsListBox.Value;
                pix=find(matches(items,aa));

                if numel(pix)==0
                    pix=1;
                else
                    pix=pix(1);
                end
            else
                pix=1;
            end

            data=app.UITable.Data;
            app.Data.groups(pix).Table=data;

        end

        % Button pushed function: PlotSignalExportButton
        function PlotSignalExportButtonPushed(app, event)
            signalParam=   app.SignalParametersPanel.UserData;

            arg={};
            %  if divParam.ExportPlot
            %  arg=[arg 'ExportPlot' ];
            %end


            arg=[arg 'FrameInterval', signalParam.FrameInterval];
            arg=[arg 'timeOrGen', signalParam.timeOrGen{end}];
            arg=[arg 'maxBirth', signalParam.maxBirth];
            arg=[arg 'MinDiv', signalParam.minDiv];
            arg=[arg 'MinSep', signalParam.minSep];

            groups=app.Data.groups;

            cc=1;
            rlstot=[];

            grcc=[];
            groups(1).div=[];

            for i=1:numel(groups)
                data=groups(i).Data;
                param=groups(i).Param ;
                name=groups(i).Name;
                tabl=groups(i).Table; 

                if numel(tabl)==0
                    continue
                end

                if param.PlotThisGroup==true % to plot

%                     claslist=param.classifierName(1:end-1);
%                     sel=param.classifierName{end};
% 
%                     pix=find(matches(claslist,sel));

                    gtpred=param.PredVSGT;

                    if gtpred==true
                        %                 if param.GroundtruthVSPredictions
                        param.comment={'Groundtruth','Prediction'};
                        param.GroundtruthVSPredictions=true;
                    else
                        param.comment={name};
                        param.GroundtruthVSPredictions=false;
                    end
                    %
                    rls=data.rls;
                    sel=find(cellfun(@(x) x==true,tabl(:,1)));
                    rls=rls(sel);

                    %
                    if gtpred==false
                        for j=1:numel(rls)
                            rls(j).condition=cc;
                        end
                        cc=cc+1;
                    end
                    %
                    groups(i).signal=rls;
                    %
                    if gtpred
                        %    if tdata{i,1}==true
                        arg2=[arg 'GT'  'NameFile' [signalParam.Filename '_' name '_predvsgt'] 'figName' name];
                        plotSignal2(rls,arg2{:});

                    else
                        %      if tdata{i,1}==true % plot
                        rlstot=[rlstot; rls];
                        grcc=[grcc; i];
                        %     end
                    end
                end
            end

            if numel(rlstot)
                arg=[arg 'figName' 'pooled groups' 'NameFile' signalParam.Filename];
                plotSignal2(rlstot,arg{:});
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DivisioncountingandRLSanalysisUIFigure = uifigure('Visible', 'off');
            app.DivisioncountingandRLSanalysisUIFigure.Name = 'Division counting and & RLS analysis';
            app.FileMenu = uimenu(app.DivisioncountingandRLSanalysisUIFigure);
            app.FileMenu.Text = 'File...';
            app.LoaddatagroupsMenu = uimenu(app.FileMenu);
            app.LoaddatagroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddatagroupsMenuSelected, true);
            app.LoaddatagroupsMenu.Text = 'Load data groups...';
            app.SavedatagroupsasMenu = uimenu(app.FileMenu);
            app.SavedatagroupsasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedatagroupsasMenuSelected, true);
            app.SavedatagroupsasMenu.Text = 'Save data groups as...';
            app.TabGroup = uitabgroup(app.DivisioncountingandRLSanalysisUIFigure);
            app.DivisionplotTab = uitab(app.TabGroup);
            app.DivisionplotTab.Title = 'Division plot';
            app.divParametersPanel = uipanel(app.DivisionplotTab);
            app.divParametersPanel.Title = 'Division plot/export parameters';
            app.PlotDivExportButton = uibutton(app.DivisionplotTab, 'push');
            app.PlotDivExportButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDivExportButtonPushed, true);
            app.PlotDivExportButton.Text = 'Plot Divisions';
            app.RLSplotTab = uitab(app.TabGroup);
            app.RLSplotTab.Title = 'RLS plot';
            app.RLSParametersPanel = uipanel(app.RLSplotTab);
            app.RLSParametersPanel.Title = 'RLS plot/export parameters';
            app.PlotRLSExportButton = uibutton(app.RLSplotTab, 'push');
            app.PlotRLSExportButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRLSExportButtonPushed, true);
            app.PlotRLSExportButton.Text = 'Plot RLS';
            app.SignalplotTab = uitab(app.TabGroup);
            app.SignalplotTab.Title = 'Signal plot';
            app.SignalParametersPanel = uipanel(app.SignalplotTab);
            app.SignalParametersPanel.Title = 'Signal export parameters';
            app.PlotSignalExportButton = uibutton(app.SignalplotTab, 'push');
            app.PlotSignalExportButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSignalExportButtonPushed, true);
            app.PlotSignalExportButton.Text = 'Plot Signals';
            app.DatagroupsPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatagroupsPanel.Title = 'Data groups';
            app.DatagroupsListBox = uilistbox(app.DatagroupsPanel);
            app.DatagroupsListBox.Items = {'group01', 'group02'};
            app.DatagroupsListBox.ValueChangedFcn = createCallbackFcn(app, @DatagroupsListBoxValueChanged, true);
            app.DatagroupsListBox.Value = 'group01';
            app.NewgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.NewgroupButton.ButtonPushedFcn = createCallbackFcn(app, @NewgroupButtonPushed, true);
            app.NewgroupButton.Text = 'New group';
            app.DeleteselectedgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.DeleteselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedgroupButtonPushed, true);
            app.DeleteselectedgroupButton.Text = 'Delete selected group';
            app.RenamegroupEditFieldLabel = uilabel(app.DatagroupsPanel);
            app.RenamegroupEditFieldLabel.Text = 'Rename group:';
            app.RenamegroupEditField = uieditfield(app.DatagroupsPanel, 'text');
            app.RenamegroupEditField.ValueChangedFcn = createCallbackFcn(app, @RenamegroupEditFieldValueChanged, true);
            app.AssignselectedgroupparamtoallButton = uibutton(app.DatagroupsPanel, 'push');
            app.AssignselectedgroupparamtoallButton.ButtonPushedFcn = createCallbackFcn(app, @AssignselectedgroupparamtoallButtonPushed, true);
            app.AssignselectedgroupparamtoallButton.Text = 'Assign selected group param to all';
            app.SaveselectedgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.SaveselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectedgroupButtonPushed, true);
            app.SaveselectedgroupButton.Text = 'Save selected group';
            app.Lamp = uilamp(app.DatagroupsPanel);
            app.DivisionextractionparametersPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.DivisionextractionparametersPanel.Title = 'Division extraction parameters';
            app.DataselectionPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.DataselectionPanel.Title = 'Data selection';
            app.ExtractdivisionsRLSButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.ExtractdivisionsRLSButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractdivisionsRLSButtonPushed, true);
            app.ExtractdivisionsRLSButton.Text = 'Extract divisions & RLS';
            app.UITable = uitable(app.DivisioncountingandRLSanalysisUIFigure);
            app.UITable.ColumnName = {'Select'; 'ROI ID'; 'DivTime'; 'RLS'; 'SEP'; 'Endtype'; 'GT'; 'Birth'; 'End'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.SelecttableindicesEditFieldLabel = uilabel(app.DivisioncountingandRLSanalysisUIFigure);
            app.SelecttableindicesEditFieldLabel.Text = 'Select table indices:';
            app.SelecttableindicesEditField = uieditfield(app.DivisioncountingandRLSanalysisUIFigure, 'text');
            app.SelecttableindicesEditField.ValueChangedFcn = createCallbackFcn(app, @SelecttableindicesEditFieldValueChanged, true);
            app.PlotDivExportButton_2 = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.PlotDivExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotDivExportButton_2Pushed, true);
            app.PlotDivExportButton_2.Text = 'Export current table as Excel file';
            app.FilenameEditFieldLabel = uilabel(app.DivisioncountingandRLSanalysisUIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.DivisioncountingandRLSanalysisUIFigure, 'text');
            app.FilenameEditField.Value = 'myexcelfile.xls';
            app.SelectgroupsofdataLabel = uilabel(app.DivisioncountingandRLSanalysisUIFigure);
            app.SelectgroupsofdataLabel.Text = '1/ Select groups of data';
            app.ComputedivisionsandRLSLabel = uilabel(app.DivisioncountingandRLSanalysisUIFigure);
            app.ComputedivisionsandRLSLabel.Text = '2/ Compute divisions and RLS:';
            app.PlotexportresultsLabel = uilabel(app.DivisioncountingandRLSanalysisUIFigure);
            app.PlotexportresultsLabel.Text = '3/ Plot/export results';
            % Show the figure after all components are created
            app.DivisioncountingandRLSanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DivisioncountingandRLSanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DivisioncountingandRLSanalysisUIFigure)
        end
    end
end