classdef export2phylocellGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhylocellexporterUIFigure      matlab.ui.Figure
        CloseButton                    matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        OptionsPanel                   matlab.ui.container.Panel
        ROIEditField                   matlab.ui.control.EditField
        ROIswillbeprocessedasseparatepositionsinphylocellEditFieldLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        PositionsEditField             matlab.ui.control.EditField
        PositionsLabel                 matlab.ui.control.Label
        FramesEditField                matlab.ui.control.EditField
        FramesEditFieldLabel           matlab.ui.control.Label
        OutputfileButtonGroup          matlab.ui.container.ButtonGroup
        ProjectlocationEditField       matlab.ui.control.EditField
        ProjectlocationEditFieldLabel  matlab.ui.control.Label
        SelectprojectButton            matlab.ui.control.Button
        ExportdatatoexistingprojectButton  matlab.ui.control.RadioButton
        NewprojectButton               matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj)
            app.Data.shallowObj=shallowObj;
            
            if numel(shallowObj.fov)
             if numel(shallowObj.fov(1).frames)>1
                    % pix=find(matches(shallowObj.fov(i).channel,strcha));
                    fra=shallowObj.fov(1).frames(1);
                else
                    fra=shallowObj.fov(1).frames;
             end
             
            app.FramesEditField.Value=['1:' num2str(fra)];
            app.PositionsEditField.Value=['1:' num2str(numel(shallowObj.fov))];
            app.ROIEditField.Value=['1:' num2str(numel(shallowObj.fov(1).roi))];
            
            app.Data.frames=app.FramesEditField.Value;
            app.Data.positions=app.PositionsEditField.Value;
            app.Data.ROI=app.ROIEditField.Value;
            
            else
                app.FramesEditField.Value='There is no FOV in this project';
                app.PositionsEditField.Value='There is no FOV in this project';
                app.ExportButton.Enable='off';
                return;
            end
            
            % gather all possible channel names on all the positions/ROIs 
            chastr={};
            cc=1;
            
            for i=1:numel(shallowObj.fov)
                for j=1:numel(shallowObj.fov(i).roi)
                    chastr=unique([chastr shallowObj.fov(i).roi(j).display.channel]);
                    cc=cc+1;
                end
            end
            
            t=app.UITable;
            t.ColumnName={'Select channel','Channel name','Type','Phylocell channel name', 'PhyloCell channel number'};
            t.ColumnEditable=[true false true true true];
            
            t.ColumnFormat={[] [] {'Raw image' 'cells1' 'bud' 'nucleus' 'mito'} [] []};
            
            Data={};
            
            for i=1:numel(chastr)    
                Data(i,:)={false chastr{i} 'Raw image' chastr{i} num2str(i)};
            end
            
            t.Data=Data;
            
            app.Data.exportparam=Data;
            app.Data.newproject=true;
            app.Data.file=[];
            app.Data.path=[];
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: PhylocellexporterUIFigure
        function PhylocellexporterUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: SelectprojectButton
        function SelectprojectButtonPushed(app, event)
            pix=app.ExportdatatoexistingprojectButton.Value;
            
            timeLapse=[];
            
            if pix==true % exisiting project 
                 [file path]=uigetfile('*.mat','Select a file and location for the existing phylocell project','my_phylocell_project.mat');
                 
                 if file~=0
                 load(fullfile(path,file)); % load timeLapse variable

                if exist('timeLapse','var')
                    
                    disp('File corresponds to a valid  timeLapse phyloCell project');
                else
                     disp('This is not a valid file ! Quitting....');
                     uilaert(app.PhylocellexporterUIFigure,'This is not a valide PhyloCell project','Error');
                     return;
                end
                
                 end
            
                 
              app.Data.newproject=false;
            else % new project 
                
               [file path]=uiputfile('*.mat','Select a file and location for the new phylocell project','my_phylocell_project.mat');
            
               app.Data.newproject=true;
            end
            
            app.ProjectlocationEditField.Value=fullfile(path,file);
            
            app.Data.path=path;
            [~, app.Data.file, ~]=fileparts(file); 


        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
           
            
            if numel(app.Data.file)==0
                
               uialert(app.PhylocellexporterUIFigure,'No output file has been selected; Quitting !','Error');
               return;
               
            end
            
            
            shallowObj=app.Data.shallowObj;
            
            app.Data.exportparam=app.UITable.Data;
           
            app.Data.positions=app.PositionsEditField.Value;
            app.Data.frames=app.FramesEditField.Value;
      
             
           d = uiprogressdlg(app.PhylocellexporterUIFigure,'Title','Please Wait...',...
                    'Message','Creating exported Phylocell project...');
           d.Value=0.1;
               
                pause(0.5);
            createPhylocellProject(app.Data,d)
            
             d.Value=0.9;
                close(d);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;


            app.Data.exportparam=app.UITable.Data;
        end

        % Value changed function: PositionsEditField
        function PositionsEditFieldValueChanged(app, event)
            value = app.PositionsEditField.Value;
            
            app.Data.positions=value;
        end

        % Value changed function: FramesEditField
        function FramesEditFieldValueChanged(app, event)
            value = app.FramesEditField.Value;
            
            app.Data.frames=value;
        end

        % Value changed function: ROIEditField
        function ROIEditFieldValueChanged(app, event)
            value = app.ROIEditField.Value;
            
            app.Data.ROI=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhylocellexporterUIFigure = uifigure('Visible', 'off');
            app.PhylocellexporterUIFigure.Name = 'Phylocell exporter';
            app.PhylocellexporterUIFigure.CloseRequestFcn = createCallbackFcn(app, @PhylocellexporterUIFigureCloseRequest, true);
            app.OutputfileButtonGroup = uibuttongroup(app.PhylocellexporterUIFigure);
            app.OutputfileButtonGroup.Title = 'Output file';
            app.NewprojectButton = uiradiobutton(app.OutputfileButtonGroup);
            app.NewprojectButton.Text = 'New project';
            app.NewprojectButton.Value = true;
            app.ExportdatatoexistingprojectButton = uiradiobutton(app.OutputfileButtonGroup);
            app.ExportdatatoexistingprojectButton.Text = 'Export data to existing project';
            app.SelectprojectButton = uibutton(app.OutputfileButtonGroup, 'push');
            app.SelectprojectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectprojectButtonPushed, true);
            app.SelectprojectButton.Text = 'Select project....';
            app.ProjectlocationEditFieldLabel = uilabel(app.OutputfileButtonGroup);
            app.ProjectlocationEditFieldLabel.Text = 'Project location:';
            app.ProjectlocationEditField = uieditfield(app.OutputfileButtonGroup, 'text');
            app.ProjectlocationEditField.Editable = 'off';
            app.OptionsPanel = uipanel(app.PhylocellexporterUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.FramesEditFieldLabel = uilabel(app.OptionsPanel);
            app.FramesEditFieldLabel.Text = 'Frames:';
            app.FramesEditField = uieditfield(app.OptionsPanel, 'text');
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.PositionsLabel = uilabel(app.OptionsPanel);
            app.PositionsLabel.Text = 'Positions:';
            app.PositionsEditField = uieditfield(app.OptionsPanel, 'text');
            app.PositionsEditField.ValueChangedFcn = createCallbackFcn(app, @PositionsEditFieldValueChanged, true);
            app.UITable = uitable(app.OptionsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ROIswillbeprocessedasseparatepositionsinphylocellEditFieldLabel = uilabel(app.OptionsPanel);
            app.ROIswillbeprocessedasseparatepositionsinphylocellEditFieldLabel.Text = 'ROIs (will be processed as separate positions in phylocell):';
            app.ROIEditField = uieditfield(app.OptionsPanel, 'text');
            app.ROIEditField.ValueChangedFcn = createCallbackFcn(app, @ROIEditFieldValueChanged, true);
            app.ExportButton = uibutton(app.PhylocellexporterUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CloseButton = uibutton(app.PhylocellexporterUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.PhylocellexporterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = export2phylocellGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhylocellexporterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhylocellexporterUIFigure)
        end
    end
end