classdef exportdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportdataGUIUIFigure         matlab.ui.Figure
        ExportButton                  matlab.ui.control.Button
        ExportfiletypeDropDown        matlab.ui.control.DropDown
        ExportfiletypeDropDownLabel   matlab.ui.control.Label
        FileButton                    matlab.ui.control.Button
        ExportfilepathEditField       matlab.ui.control.EditField
        ExportfilepathEditFieldLabel  matlab.ui.control.Label
        CheckdatatoexportPanel        matlab.ui.container.Panel
        RefreshButton                 matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        ChooseProjectPositionROIstoexportPanel  matlab.ui.container.Panel
    end


    properties (Access = private)
        Data=[]; % Description
    end

    methods (Access = private)


        function displayFields(app)
            Tree = uitree(app.CheckdatatoexportPanel,'checkbox');
            Tree.CheckedNodesChangedFcn = {@checkChanged};
            Tree.Tooltip = {'Select data from projects loaded in memory'};
            Tree.Position = [10 10 550 340];
            app.Data.datatree=Tree;


%             TrainNode = uitreenode(Tree);
%             TrainNode.Text = 'Training';
%             TrainNode.Tag='Training';

%             ResultsNode = uitreenode(Tree);
%             ResultsNode.Text = 'Results';
%             ResultsNode.Tag='Results';

            %expand(IndependentClassifiersNode);

            %tmp= app.Data.tree.UserData.nodeid

            tmp= app.Data.tree.UserData.roiobj; % list of selected rois in the left tree nodes

            app.Data.rois=tmp;
            %     tmp= app.Data.tree.UserData.filepath;
            %     tmp=app.Data.tree.UserData.type
            fields=gatherROIsfields(app,tmp);

            %h1=[]; h2=[];

%             for i=1:numel(fields.Training{1})
%                 %  udata='ok';
%                 if numel(fields.Training{2}{i})
%                 h1(i)=uitreenode(TrainNode,'Text',fields.Training{1}{i},'Tag','Training','UserData',[]);
% 
%                 for j=1:numel(fields.Training{2}{i})
% 
%                     str= [fields.Training{2}{i}{j} fields.Training{3}{i}{j}];
% 
%                     h2(i,j)=uitreenode(h1(i),'Text',str,'Tag','Training','UserData',...
%                         {fields.Training{1}{i} fields.Training{2}{i}{j}} );
%                 end
%                 end
%             end
% 
%             expand(TrainNode);


            for i=1:numel(fields.Results{1})
                %  udata='ok';
                if numel(fields.Results{2}{i})
                h3(i)=uitreenode(Tree,'Text',fields.Results{1}{i},'Tag','Results','UserData',[]);

                for j=1:numel(fields.Results{2}{i})

                    str= [fields.Results{2}{i}{j} fields.Results{3}{i}{j}];

                    h4(i,j)=uitreenode(h3(i),'Text',str,'Tag','Results','UserData',...
                        {fields.Results{1}{i} fields.Results{2}{i}{j}} );
                end
                end
            end

            expand(Tree);

            function checkChanged(src, event)
                %tmp=app.Data.datatree

                %aa=event.CheckedNodes(2)
            end


        end

        function fields=gatherROIsfields(app,rois)

            fields=[];
            %fields={[] [] []};
            fields.Results={[] [] []};

            if numel(rois)==0
                return
            end

%             % training fields
%             tmpr={};
%             cc=1;
%             for i=1:numel(rois)
%                 if numel(rois(i).train)
%                     tmp=fieldnames(rois(i).train);
% 
%                     tmpr=[tmpr tmp'];
%                     cc=cc+1;
%                 end
%             end
%             tmpr=unique(tmpr);
% 
%             subt={};
%             subcat={};
% 
%             for i=1:numel(tmpr)
% 
%                 tt={};
%                 catr={};
%            
%                 for j=1:numel(rois)
%                     if numel(rois(j).train)==0
%                         continue
%                     end
% 
%                     tmpr2=fieldnames(rois(j).train);
% 
%                     if numel(tmpr2)
%                         pix=matches(tmpr2,tmpr{i});
% 
%                       %  pix=pix(pix>0);
% 
%                         if sum(pix)>0
% 
%                             subtmpr=fieldnames(rois(j).train.(tmpr2{pix}));
% 
%                             for k=1:numel(subtmpr)
% 
%                                 if ~isstruct(rois(j).train.(tmpr2{pix}).(subtmpr{k}))
% 
%                                     tt=[tt subtmpr(k)];
% 
%                                     catr=[catr [' // ' num2str(length(rois(j).train.(tmpr2{pix}).(subtmpr{k}))) ' elements // ' class(rois(j).train.(tmpr2{pix}).(subtmpr{k})) ' ']];
%                                 end
%                             end
%                             %   end
%                         end
%                     end
%                 end
% 
%                 [ttt,ia,~]=unique(tt);
%                 catrr=catr(ia);
% 
%                 subt{i}=ttt;
%                 subcat{i}=catrr;
%             end
% 
%             fields.Training={tmpr subt subcat};

            % results fields
            tmpr={};
            idx={};

            cc=1;
            for i=1:numel(rois)
                for j=1:numel(rois(i).data)
                    tmpr=[tmpr rois(i).data(j).groupid];
                    idx=[idx rois(i).data(j).id];
                    cc=cc+1;
                end
            end

            [tmpr,ia]=unique(tmpr);

         %   tmpr=tmpr(ia)


            subt={};
            subcat={};


            for i=1:numel(tmpr)

                tt={};
                catr={};

                for j=1:numel(rois)
  
                    pix=find(matches({rois(j).data.groupid},tmpr(i)));

                    if numel(pix)
                        %pix=matches(tmpr2,tmpr{i});
                        
                        % if sum(pix)>0

                           subtmpr=rois(j).data(pix).data.Properties.VariableNames;
                            
                           si=rois(j).data(pix).dataSize;

                            for k=1:numel(subtmpr)
% 
%                                 if ~isstruct(rois(j).results.(tmpr2{pix}).(subtmpr{k}))
% 
                                    tt=[tt subtmpr(k)];
% 
                                  
                                     catr=[catr [' // ' num2str(si(1)) ' elements // ' class(rois(j).data(pix).data.(subtmpr{k})) ' ']];
%                                 end
%                             end
                             end


                    end
                    %   end
                end

                [ttt,iaa,~]=unique(tt);
                catrr=catr(iaa);

                subt{i}=ttt;
                subcat{i}=catrr;
            end

           % subt,subcat
            fields.Results={tmpr subt subcat};
        end
    end

    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [datatree,~]=datapickerGUI2('Handle',app.ChooseProjectPositionROIstoexportPanel,'Position',[10 10 280 550], 'Flag',app.Lamp);
            app.Data.tree=datatree;
            app.Data.rois=[];
            displayFields(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)


            displayFields(app)
            % t=app.Data.datatree

           if numel(app.Data.datatree.Children)
            app.Data.datatree.CheckedNodes=app.Data.datatree.Children;
           end

            app.Lamp.Color=[0 1 0];

        end

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
            [file,path] = uiputfile('*.xlsx','Enter the path and filename of the exported file',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);
                app.ExportfilepathEditField.Value=filename;
                app.Data.file=filename;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            rois=app.Data.rois;
             
            if numel(rois)==0
                uialert(app.ExportdataGUIUIFigure,'First select at least one ROI !', 'Warning');
                return;
            end


if isfield(app.Data,'file')
            filename=app.Data.file;
else
uialert(app.ExportdataGUIUIFigure,'Please specify a filename first !','Warning');
return;
end

            [p ,f ,ext]=fileparts(filename);

            if numel(filename)==0
                uialert(app.ExportdataGUIUIFigure,'First select a valid filename first !', 'Warning');
                return;
            end

            data=app.Data.datatree;

disp('Please wait and do not access file until writing is complete....')
              disp('It may take a few minutes !')


            for i=1:numel(data.CheckedNodes)
                %i
                %toto=data.CheckedNodes(i).UserData
                if numel(data.CheckedNodes(i).UserData)>0
%                      text=data.CheckedNodes(i).Parent.Parent.Text
%                     if strcmp(text,'Training')
%                         typ='train';
%                     else
%                         typ='results';
%                     end
                    d=data.CheckedNodes(i).UserData;
                    outfile=fullfile(p,[f '_' d{1} '.xlsx']); %write as xlsx is important , otherwise throw an error with large files 

                    if exist(outfile)
                        delete(outfile);
                    end
                end
            end

            for i=1:numel(data.CheckedNodes)
                if numel(data.CheckedNodes(i).UserData)>0
                    % determine if node is results or training
%                     text=data.CheckedNodes(i).Parent.Parent.Text;
%                     if strcmp(text,'Training')
%                         typ='train';
%                     else
%                         typ='results';
%                     end

                    d=data.CheckedNodes(i).UserData;

                    strtot={};
                    cc=1;
                    for j=1:numel(rois)
                        
                        groups={rois(j).data.groupid};
                        pix=find(matches(groups,d{1}));

                        if numel(pix)
                            out= rois(j).data(pix).getData(d{2});

                            str={};
                            str{1}=rois(j).id;


                             if iscell(out)
                                    str=[str out'];
                            else
                                    str2=num2cell(out');
                                     ny=size(str2,1);
                                     if ny>1
                                         str(2:ny,1)={[]};
                                     end
 
                                     str=[str str2];
                             end


                            strtot((cc-1)*size(str,1)+1:cc*size(str,1),1:size(str,2))=str;
                            cc=cc+1;

         
                        end

%                         if isfield(rois(j).(typ),d{1})
%                             if isfield(rois(j).(typ).(d{1}),d{2})
% 
%                                 out=rois(j).(typ).(d{1}).(d{2});
%                                 str={};
%                                 str{1}=rois(j).id;
% 
%                                 if iscell(out)
%                                     str=[str out'];
%                                 else
%                                     str2=num2cell(out);
%                                     ny=size(str2,1);
%                                     if ny>1
%                                         str(2:ny,1)={[]};
%                                     end
% 
%                                     str=[str str2];
%                                 end
% 
% 
%                                 strtot((cc-1)*size(str,1)+1:cc*size(str,1),1:size(str,2))=str;
%                                 % strtot
%                                 cc=cc+1;
%                             end
%                         end
                        
            
                    end

                    if cc>1
                        tt=fullfile(p,[f '_' d{1} '.xlsx']);
                        aa=d{2};
                        writecell(strtot,fullfile(p,[f '_' d{1} '.xlsx']),'sheet',d{2},'WriteMode','overwritesheet');
                    end


                end
            end

            disp('Export is done !')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportdataGUIUIFigure = uifigure('Visible', 'off');
            app.ExportdataGUIUIFigure.Name = 'Export data GUI';
            app.ChooseProjectPositionROIstoexportPanel = uipanel(app.ExportdataGUIUIFigure);
            app.ChooseProjectPositionROIstoexportPanel.Title = 'Choose Project/Position/ROIs to export';
            app.Lamp = uilamp(app.ExportdataGUIUIFigure);
            app.RefreshButton = uibutton(app.ExportdataGUIUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh ';
            app.CheckdatatoexportPanel = uipanel(app.ExportdataGUIUIFigure);
            app.CheckdatatoexportPanel.Title = 'Check data to export';
            app.ExportfilepathEditFieldLabel = uilabel(app.ExportdataGUIUIFigure);
            app.ExportfilepathEditFieldLabel.Text = 'Export file path:';
            app.ExportfilepathEditField = uieditfield(app.ExportdataGUIUIFigure, 'text');
            app.FileButton = uibutton(app.ExportdataGUIUIFigure, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Text = 'File...';
            app.ExportfiletypeDropDownLabel = uilabel(app.ExportdataGUIUIFigure);
            app.ExportfiletypeDropDownLabel.Text = 'Export file type';
            app.ExportfiletypeDropDown = uidropdown(app.ExportdataGUIUIFigure);
            app.ExportfiletypeDropDown.Items = {'XLS'};
            app.ExportfiletypeDropDown.Value = 'XLS';
            app.ExportButton = uibutton(app.ExportdataGUIUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.ExportdataGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exportdata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportdataGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportdataGUIUIFigure)
        end
    end
end