classdef lifespan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LifespanizerUIFigure          matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        CreatenewprojectorselectprojectinworkspaceMenu  matlab.ui.container.Menu
        OpenprojectMenu               matlab.ui.container.Menu
        SaveprojectMenu               matlab.ui.container.Menu
        CloseprojectMenu              matlab.ui.container.Menu
        SetclassifierrepositorypathMenu  matlab.ui.container.Menu
        erasedataMenu                 matlab.ui.container.Menu
        applysettingstoallpositionsButton  matlab.ui.control.Button
        ResetframeselectionButton     matlab.ui.control.Button
        ResetROIsselectionButton      matlab.ui.control.Button
        DeselectallButton             matlab.ui.control.Button
        SelectallButton               matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        LoaddataTab                   matlab.ui.container.Tab
        LoadAdddataButton             matlab.ui.control.Button
        PreprocessingTab              matlab.ui.container.Tab
        ParametersLabel               matlab.ui.control.Label
        EditpreprocessingparametersButton  matlab.ui.control.Button
        CombinemultiplezstackintoasinglechannelLabel  matlab.ui.control.Label
        RunpreprocessingButton        matlab.ui.control.Button
        ClassifyTab                   matlab.ui.container.Tab
        RepositoryPathLabel           matlab.ui.control.Label
        DivClassifierLabel            matlab.ui.control.Label
        MetricsLabel                  matlab.ui.control.Label
        RLSLabel                      matlab.ui.control.Label
        CellmetricsButton             matlab.ui.control.Button
        SetRLSButton                  matlab.ui.control.Button
        CellClassifierLabel           matlab.ui.control.Label
        setclassifierButton_2         matlab.ui.control.Button
        setclassifierButton           matlab.ui.control.Button
        postprocessingCheckBox        matlab.ui.control.CheckBox
        cellsegmentationCheckBox      matlab.ui.control.CheckBox
        divisionclassifierCheckBox    matlab.ui.control.CheckBox
        ClassifyProcessButton         matlab.ui.control.Button
        ExportTab                     matlab.ui.container.Tab
        SetxlsxexportpathButton       matlab.ui.control.Button
        DatasheetexportfilexlsxEditField  matlab.ui.control.EditField
        DatasheetexportfilexlsxLabel  matlab.ui.control.Label
        PlotDataButton                matlab.ui.control.Button
        ExportDataButton              matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        UIPositionTable               matlab.ui.control.Table
    end


    properties (Access = public)
        Data % Description
        shallowObj % Description
        processObj % Description
        UserData;
    end

    methods (Access = public)

        function classiObj = getClassificationByName(app, targetName)
            % getClassificationByName recherche dans app.shallowObj.processing.classification
            % l'instance dont le champ strid, dénué du suffixe "_XX", correspond à targetName.
            %
            % INPUT :
            %   app        - l'objet app contenant app.shallowObj.processing.classification
            %   targetName - le nom de base à rechercher (par exemple, 'divisionClassifier')
            %
            % OUTPUT :
            %   classiObj  - l'instance trouvée, ou [] si aucune correspondance n'est trouvée

            classiObj = [];
            % Vérifier que la classification existe
            if ~isfield(app.shallowObj.processing, 'classification') || isempty(app.shallowObj.processing.classification)
                return;
            end

            allClassi = app.shallowObj.processing.classification;
            % Construire un pattern regex qui accepte targetName seul ou suivi de _ et de chiffres
            % La fonction regexptranslate 'escape' protège targetName s'il contient des caractères spéciaux
            pattern = ['^', regexptranslate('escape', targetName), '(_\d+)?$'];

            for k = 1:numel(allClassi)
                if isprop(allClassi(k), 'strid')
                    if ~isempty(regexp(allClassi(k).strid, pattern, 'once'))
                        classiObj = allClassi(k);
                        return;
                    end
                end
            end
        end

        function procObj = getProcessorByName(app, targetName)
            % getProcessorByName recherche dans app.shallowObj.processing.processor
            % l'instance dont le champ strid, dénué du suffixe "_XX", correspond à targetName.
            %
            % INPUT :
            %   app        - l'objet app contenant app.shallowObj.processing.processor
            %   targetName - le nom de base à rechercher (exemple : 'myProcessor')
            %
            % OUTPUT :
            %   procObj    - l'instance trouvée, ou [] si aucune correspondance n'est trouvée

            procObj = [];
            % Vérifier que la propriété processor existe et n'est pas vide
            if ~isfield(app.shallowObj.processing, 'processor') || isempty(app.shallowObj.processing.processor)
                return;
            end

            allProc = app.shallowObj.processing.processor;
            % Construire un pattern regex pour accepter targetName seul ou suivi de _ et de chiffres
            pattern = ['^', regexptranslate('escape', targetName), '(_\d+)?$'];

            for k = 1:numel(allProc)
                if isprop(allProc(k), 'strid')
                    if ~isempty(regexp(allProc(k).strid, pattern, 'once'))
                        procObj = allProc(k);
                        return;
                    end
                end
            end
        end

  function isClassified = checkDataClassified(app, fovIndex)
    % checkDataClassified vérifie pour le FOV d'indice fovIndex si
    % au moins une ROI possède, dans son tableau data, un élément dont le
    % champ groupid est identique au strid du classifier "divisionClassifier" 
    % ou correspond au format 'RLS_divisionClassifier_XX'.
    %
    % INPUT :
    %   app      - l'application contenant app.shallowObj
    %   fovIndex - l'indice du FOV à vérifier
    %
    % OUTPUT :
    %   isClassified - true si au moins une ROI est classifiée, false sinon

    isClassified = false;
    
    % Vérifier que les FOV existent
    if isempty(app.shallowObj) || ~isprop(app.shallowObj, 'fov') || isempty(app.shallowObj.fov)
        return;
    end
    
    % Vérifier que fovIndex est valide
    if fovIndex < 1 || fovIndex > numel(app.shallowObj.fov)
        warning('fovIndex out of range');
        return;
    end
    
    fovObj = app.shallowObj.fov(fovIndex);
    
    % Récupérer le strid du classifier "divisionClassifier"
    classifier = getClassificationByName(app, 'divisionClassifier');
    if ~isempty(classifier) && isprop(classifier, 'strid')
        divStrid = classifier.strid;
    else
        divStrid = '';
    end
    
    % Vérifier que le FOV possède des ROIs
    if ~isprop(fovObj, 'roi') || isempty(fovObj.roi)
        return;
    end
    rois = fovObj.roi;
    
    % Parcourir chaque ROI du FOV
    for j = 1:numel(rois)
        roiObj = rois(j);
        if isprop(roiObj, 'data') && ~isempty(roiObj.data)
            % Parcourir tous les éléments de roiObj.data
            for d = 1:numel(roiObj.data)
                % Vérifier que l'élément possède le champ 'groupid'
                if isprop(roiObj.data(d), 'groupid')
                    groupidVal = roiObj.data(d).groupid;
                    % Condition 1 : correspond exactement au strid
                    if ~isempty(divStrid) && strcmp(groupidVal, divStrid)
                        isClassified = true;
                        return;
                    end
                    % Condition 2 : correspond au format 'RLS_divisionClassifier_XX'
                    pattern = '^RLS_divisionClassifier_\d+$';
                    if ~isempty(regexp(groupidVal, pattern, 'once'))
                        isClassified = true;
                        return;
                    end
                end
            end
        end
    end
end


        function displayPositions(app)

      
            % Vérifier que l'objet shallow existe et contient des informations sur les FOV
            if isempty(app.shallowObj) || ~isprop(app.shallowObj, 'fov') || isempty(app.shallowObj.fov)
                uialert(app.LifespanizerUIFigure, 'No field-of-view data available in the project.', 'Error');
                return;
            end

 
            fovs = app.shallowObj.fov;
            nFOV = numel(fovs);

            % On suppose que app.shallowObj.parsedData.positions existe
            parsedPositions = [];
            if isprop(app.shallowObj, 'parsedData') && isfield(app.shallowObj.parsedData, 'positions')
                parsedPositions = app.shallowObj.parsedData.positions;
            end

            if nFOV==1 && numel(app.shallowObj.fov(1).srclist)==0
                  app.UIPositionTable.Data = {};
                  app.shallowObj.processing.pipeline.positionTable={};
                return;

            end

            % Si la pipeline existe déjà, on l'utilise pour les champs éditables.
            if isfield(app.shallowObj.processing, 'pipeline') && ~isempty(app.shallowObj.processing.pipeline) && isfield(app.shallowObj.processing.pipeline, 'positionTable')
                
                tableData = app.shallowObj.processing.pipeline.positionTable;
                % Si le nombre de lignes ne correspond pas au nombre de FOV, on réinitialise
                if size(tableData,1) ~= nFOV
                    tableData = cell(nFOV, 11);
                end
            else
                tableData = cell(nFOV, 11);
            end


            % Initialisation des flags pour la visibilité des tabs
            anyExtracted = false;
            anyPostProcessed = false;

            % Tester si le nom de channel de sortie existe dans l'objet processObj
            outputNameExists = false;

            processObj = getProcessorByName(app, 'CombineChannel');

            if ~isempty(processObj) && isprop(processObj, 'processArg')
                procArg = processObj.processArg;
                if isstruct(procArg) && isfield(procArg, 'outputChannelName') && ~isempty(procArg.outputChannelName)
                    outputName = procArg.outputChannelName;
                    outputNameExists = true;
                end
            end

            for i = 1:nFOV
               
                % --- Colonnes éditables ou calculées par défaut ---
                % Colonne 1 : Check (editable)
                if isempty(tableData{i,1})
                    tableData{i,1} = true;
                end

                % Colonne 2 : id (non éditable, forcé)
                tableData{i,2} = i;

                % Colonne 3 : name (non éditable)
                if isprop(fovs(i), 'id')
                    tableData{i,3} = fovs(i).id;
                else
                    tableData{i,3} = 'noname';
                end

                % Colonne 4 : comment (editable)
                if isempty(tableData{i,4})
                    if isprop(fovs(i), 'comment')
                        tableData{i,4} = fovs(i).comments;
                    else
                        tableData{i,4} = '';
                    end
                end

                % Colonne 5 : #ROIs (editable)
                if isprop(fovs(i), 'roi') && ~isempty(fovs(i).roi) && isprop(fovs(i).roi(1), 'id')
                    roiCount = numel(fovs(i).roi);
                    if isempty(tableData{i,5})
                        tableData{i,5} = ['1:' num2str(roiCount)];
                    end
                    % Charger l'image associée au premier ROI pour vérifier si elle est extraite
                    fovs(i).roi(1).load;
                    if ~isempty(fovs(i).roi(1).image)
                        extracted = true;
                    else
                        extracted = false;
                    end
                else
                    tableData{i,5} = '0';
                    extracted = false;
                end


                % Colonne 6 : frames (editable)
                if isempty(tableData{i,6}) | strcmp(tableData{i,6},'N/A');
                    framesStr = 'N/A';


                    if ~isempty(parsedPositions)
                        if isprop(fovs(i), 'id')
                            rawFovID = fovs(i).id;
                            modifiedFovID = regexprep(rawFovID, '_\d+', '');
                        else
                            modifiedFovID = sprintf('FOV%d', i);
                        end
                        posFound = false;
                        for k = 1:numel(parsedPositions)
                            if isfield(parsedPositions(k), 'userName') && strcmpi(parsedPositions(k).userName, modifiedFovID)
                                if isfield(parsedPositions(k), 'currentMinFrame') && isfield(parsedPositions(k), 'currentMaxFrame')
                                    framesStr = sprintf('%d:%d', parsedPositions(k).currentMinFrame, parsedPositions(k).currentMaxFrame);
                                else
                                    framesStr = 'N/A';
                                end
                                posFound = true;
                                break;
                            end
                        end
                        if ~posFound
                            framesStr = 'N/A';
                        end
                    end
                    tableData{i,6} = framesStr;
                end

                % --- Colonnes calculées automatiquement ---
                % Colonne 7 : extracted
                tableData{i,7} = extracted;
                if extracted
                    anyExtracted = true;
                end

                % Colonne 8 : preprocessed
                % Par défaut false, mais si outputNameExists, on vérifie dans le premier ROI du FOV
                tableData{i,8} = false;
                if outputNameExists && isprop(fovs(i), 'roi') && ~isempty(fovs(i).roi)
                    roi1 = fovs(i).roi(1);
                    if isprop(roi1, 'display') && isfield(roi1.display, 'channel')
                        channelList = roi1.display.channel;
                        if any(strcmp(channelList, outputName))
                            tableData{i,8} = true;
                        end
                    end
                end

                % Colonne 9 : pp (postprocessing ou classification) par défaut false
                tableData{i,9} =  checkDataClassified(app, i);
                % Ici, vous pouvez ajouter d'autres critères pour définir la valeur pp

                % Colonne 10 : open (non éditable, valeur par défaut 'Open')
                tableData{i,10} = 'Open';
                

                if  tableData{i,7}
                if strcmp( tableData{i,11} ,'N/A') || isempty(tableData{i,11})
                tableData{i,11} = ['1:' num2str(min(10,roiCount))];
                end
                else
                 tableData{i,11} = 'N/A';
                end

            % Affecter le tableau à la table UIPositionTable de l'app
            app.UIPositionTable.Data = tableData;

            % Sauvegarder le pipeline mis à jour dans l'objet shallowObj
            app.shallowObj.processing.pipeline.positionTable = tableData;

            % Rendre visible le tab PreprocessingTab si au moins une FOV a extracted==true
            
          extractedFlags = cellfun(@(x) ~isempty(x) && ((islogical(x) && x) || (isnumeric(x) && x==1)), tableData(:,7));
if any(extractedFlags)
    app.PreprocessingTab.Parent = app.TabGroup;
else
    app.PreprocessingTab.Parent = [];
end

% Rendre visible le tab ClassifyTab si au moins une FOV a preprocessed==true
preprocessedFlags = cellfun(@(x) ~isempty(x) && ((islogical(x) && x) || (isnumeric(x) && x==1)), tableData(:,8));
if any(preprocessedFlags)
    app.ClassifyTab.Parent = app.TabGroup;
else
    app.ClassifyTab.Parent = [];
end

% Rendre visible le tab ExportTab si au moins une FOV a pp==true
ppFlags = cellfun(@(x) ~isempty(x) && ((islogical(x) && x) || (isnumeric(x) && x==1)), tableData(:,9));
if any(ppFlags)
    app.ExportTab.Parent = app.TabGroup;
else
    app.ExportTab.Parent = [];
end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Text='';

            app.PreprocessingTab.Parent=[];
            app.ClassifyTab.Parent=[];
            app.ExportTab.Parent=[];

            % Définir le chemin de base
pth = userpath;

% Définir le dossier Detecdiv et s'assurer qu'il existe
detecdivFolder = fullfile(pth, 'Detecdiv');
if ~exist(detecdivFolder, 'dir')
    mkdir(detecdivFolder);
end

% Chemin complet du fichier repositoryPath.mat
repoFile = fullfile(detecdivFolder, 'repositoryPath.mat');

% Vérifier si le fichier existe
if exist(repoFile, 'file')
    S = load(repoFile, 'repopth'); % Charger la variable repopth
    if isfield(S, 'repopth') && ~isempty(S.repopth)
        % Afficher le chemin dans le label
        app.RepositoryPathLabel.Text = ['Repository path: ' S.repopth]; 
    else
        app.RepositoryPathLabel.Text = 'Repository path not found in file.';
    end
else
    app.RepositoryPathLabel.Text = 'repositoryPath.mat not found; please set the path in the File... menu !';
end


        end

        % Button pushed function: LoadAdddataButton
        function LoadAdddataButtonPushed(app, event)
          % Vérifier si un projet est déjà chargé dans app.shallowObj


    if isempty(app.shallowObj)
           app.CreatenewprojectorselectprojectinworkspaceMenuSelected(event);
    end


    if ~isempty(app.shallowObj)
        
        projName = app.shallowObj.io.file;
        % Vérifier que le projet existe dans l'espace de travail de base
        if evalin('base', sprintf('exist(''%s'', ''var'')', projName))

            shallowObj = evalin('base', projName);
            
            loadData(shallowObj, app);
            uiwait(app.LifespanizerUIFigure);
            
         %   if ~isempty(app.shallowObj)
       %         shallowObj = app.shallowObj;
                app.Label.Text = fullfile(shallowObj.io.path, [shallowObj.io.file, '.mat']);
                 app.shallowObj = shallowObj;
                displayPositions(app);
       %     else
       %         % Restaurer l'ancien shallowObj si nécessaire
     %  shallowObj
               
      %      end
            
        else
            uialert(app.LifespanizerUIFigure, ...
                'The specified project does not exist in the workspace.', ...
                'Error');
        end
    else


    end


        end

        % Button pushed function: RunpreprocessingButton
        function RunpreprocessingButtonPushed(app, event)

            %% Vérifier que les positions sélectionnées ont leurs ROI extraites
            tableData = app.UIPositionTable.Data;
            if isempty(tableData)
                uialert(app.LifespanizerUIFigure, 'No positions available.', 'Error');
                return;
            end

            nRows = size(tableData, 1);
            for r = 1:nRows
                % Vérifier pour chaque ligne cochée (colonne 1)
                if isequal(tableData{r, 1}, true)
                    % Vérifier que la colonne "extracted" (colonne 6) est true
                    if ~isequal(tableData{r, 7}, true)
                        uialert(app.LifespanizerUIFigure, ...
                            sprintf('ROI not extracted for position %d. Please extract ROI first.', r), ...
                            'Error');
                        return;
                    end
                end
            end


            %% Récupérer les positions sélectionnées dans la table
            data = app.UIPositionTable.Data;
            selpos = find(cellfun(@(x) x==1, data(:,1)));
            if isempty(selpos)
                uialert(app.LifespanizerUIFigure, 'No positions selected.', 'Error');
                return;
            end

            % Initialiser les variables pour frames et ROI
            frames = {};
            roiobj = [];
            cc = 1;

            shallowObj=app.shallowObj;
            processObj = getProcessorByName(app, 'CombineChannel');
            %processObj=app.processObj;


            % Pour chaque position sélectionnée, parcourir les FOV dans shallowObj
            for i = 1:numel(selpos)
                idx = selpos(i);

                selectedROIs=  str2num(data{idx,5});

                for j =  selectedROIs
                    frames{cc} = -1;  %#ok<AGROW>
                    roiobj = [roiobj, shallowObj.fov(idx).roi(j)];  %#ok<AGROW>
                    cc = cc + 1;
                end
            end


            %% Lancer le processing
            d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
                'Message', 'Starting data pre-processing...');
            d.Value = 0.01;
            drawnow;

            % Construire les arguments pour le processing
            arg = {'Progress', d, 'Frames', frames};

            tic
            processData(processObj, roiobj, arg{:});
            toc

            % Vous pouvez fermer la progress dialog ou la mettre à jour après processing
            d.Value = 1;
            pause(0.2);
            close(d);

            displayPositions(app);

        end

        % Button pushed function: ClassifyProcessButton
        function ClassifyProcessButtonPushed(app, event)

            %% Récupération des positions sélectionnées dans UIPositionTable
            % La première colonne (colonne 1) est éditable et indique les FOV sélectionnés (check == true)
            data = app.UIPositionTable.Data;
            selpos = find(cellfun(@(x) isequal(x, true), data(:,1)));

            frames = {};
            channel = {};
            roiobj = [];
            cc = 1;

            % Pour chaque FOV sélectionné dans la table (les lignes correspondent aux fovs dans app.shallowObj.fov)
            for i = 1:numel(selpos)
                idx = selpos(i);
                % Récupérer le FOV correspondant
                fovObj = app.shallowObj.fov(idx);
                % On suppose que chaque FOV possède un tableau de ROI dans fovObj.roi
                fovobj = fovObj.roi;

                fra= str2num(data{idx, 6});  %#ok<ST2NM>
                rois=str2num(data{idx, 5});  %#ok<ST2NM>

                roiobj=[roiobj fovobj(rois)];

                for j=1:numel(rois)
                    channel{cc} = 'CombinedChannel';

                    frames{cc} = fra;
                    cc = cc + 1;
                end

            end


            %% division classification
            if app.divisionclassifierCheckBox.Value==1
                classif = app.getClassificationByName('divisionClassifier')

                if numel(classif)==0
                    uialert(app.LifespanizerUIFigure, 'No division classifier set', 'Error');
                    return;
                end

                % Création d'une boîte de progression et construction des arguments
                d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
                    'Message', 'Starting division classification...');
                pause(1);
                d.Value = 0.01;

                arg = {'Progress', d, 'Channel', channel, 'Frames', frames};
                arg = [arg, {'GPU'}];
                % end
                classifyData(classif, roiobj, arg{:});
            end


            %% cell segmentation
            if app.cellsegmentationCheckBox.Value==1
                classif = app.getClassificationByName('cellsegClassifier');

                if numel(classif)==0
                    uialert(app.LifespanizerUIFigure, 'No division classifier set', 'Error');
                    return;
                end

                % Création d'une boîte de progression et construction des arguments
                d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
                    'Message', 'Starting division classification...');
                d.Value = 0.01;
                pause(1);

                arg = {'Progress', d, 'Frames', frames};
                arg = [arg, {'GPU'}];
                % end
                classifyData(classif, roiobj, arg{:});
                close(d);
            end


            %% post processing
            if app.postprocessingCheckBox.Value==1

                processObj=app.getProcessorByName('ComputeRLS');

                if numel(processObj)

                    d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
                        'Message', 'Starting RLS analysis...');
                    d.Value = 0.01;
                    drawnow;

                    % Construire les arguments pour le processing
                    arg = {'Progress', d, 'Frames', frames};


                    classiObj=app.getClassificationByName('divisionClassifier');
            if numel(classiObj)==0
                uialert(app.LifespanizerUIFigure, 'Please set the division classifier first  !', 'Error');
                return;
            end


                  processObj.processArg.classification_data={classiObj.strid classiObj.strid};


                    tic
                    processData(processObj, roiobj, arg{:});
                    toc

                    % Vous pouvez fermer la progress dialog ou la mettre à jour après processing
                    d.Value = 1;
                    pause(0.2);
                    close(d);

                end

                processObj=app.getProcessorByName('ComputeAllMetrics');

                if numel(processObj)

                    d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
                        'Message', 'Starting compute All metrics...');
                    pause(1);
                    d.Value = 0.01;
                    drawnow;

                    % Construire les arguments pour le processing
                    arg = {'Progress', d, 'Frames', frames};

          
            processObj.processArg.mask1_name={['results_' classiObj.strid] ['results_' classiObj.strid]};
            processObj.processArg.mask1_stat=true;
            processObj.processArg.mask2_stat=false;

                    tic
                    processData(processObj, roiobj, arg{:});
                    toc

                    % Vous pouvez fermer la progress dialog ou la mettre à jour après processing
                    d.Value = 1;
                    pause(0.2);
                    close(d);

                end
            end


            displayPositions(app);


        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
         groups=evalin('base','datagroups');

            if numel(app.DatasheetexportfilexlsxEditField.Value)
                filename=app.DatasheetexportfilexlsxEditField.Value;
            else
                uialert(app.DataexportingandplottingUIFigure,'Please specify a filename first !','Warning');
                return;
            end

            if numel(filename)==0
                uialert(app.DataexportingandplottingUIFigure,'First select a valid filename first !', 'Warning');
                return;
            end

         for i=1:numel(groups)
            rois=groups(i).Data.roiobj;

            datagroup=groups(i);
            exportData(datagroup,rois,fullfile(filename,[datagroup.Name '_export.xlsx']));
         end

        end

        % Menu selected function: 
        % CreatenewprojectorselectprojectinworkspaceMenu
        function CreatenewprojectorselectprojectinworkspaceMenuSelected(app, event)
    % Crée la boîte de dialogue modale (dialog)
    dlg = dialog('Name', 'Select or Create a Project', 'Position', [100 100 600 300]);

    % Récupération de la liste des variables dans l'espace de travail
    wsInfo = evalin('base', 'whos');
    varNames = {};
    varDisplay = {};
    for i = 1:length(wsInfo)
        if strcmp(wsInfo(i).class, 'shallow')
            vName = wsInfo(i).name;
            try
                projObj = evalin('base', vName);
                % Vérifier que l'objet possède le champ io et le sous-champ path
                if isprop(projObj, 'io') && isfield(projObj.io, 'path')
                    projPath = projObj.io.path;
                else
                    projPath = 'Path non défini';
                end
            catch
                projPath = 'Erreur lors de la récupération';
            end
            % Stocker le nom de variable et sa représentation pour l'affichage
            varNames{end+1} = vName; %#ok<AGROW>
            varDisplay{end+1} = sprintf('%s - %s', vName, projPath); %#ok<AGROW>
        end
    end
    if isempty(varDisplay)
        varDisplay = {'(Aucun projet shallow disponible)'};
    end

    % Créer la ListBox avec uicontrol et stocker le mapping (UserData)
    lb = uicontrol('Parent', dlg, 'Style', 'listbox', ...
                   'Position', [20 80 550 150], 'String', varDisplay, ...
                   'UserData', varNames);

    % Bouton "Select Project" : charge le projet sélectionné
    btnSelect = uicontrol('Parent', dlg, 'Style', 'pushbutton', 'String', 'Select Project', ...
                          'Position', [20 20 100 40], 'Callback', @(src,evt) selectProj());
    
    % Bouton "New Project" : crée un nouveau projet en appelant shallowNew
    btnNew = uicontrol('Parent', dlg, 'Style', 'pushbutton', 'String', 'New Project', ...
                       'Position', [150 20 100 40], 'Callback', @(src,evt) newProj());
    
    % Bouton "Cancel" : ferme la boîte de dialogue sans action
    btnCancel = uicontrol('Parent', dlg, 'Style', 'pushbutton', 'String', 'Cancel', ...
                          'Position', [280 20 100 40], 'Callback', @(src,evt) cancelPopup());
    
    % Bloquer l'exécution tant que la boîte de dialogue reste ouverte
    uiwait(dlg);
    
    % === Callback locaux ===
    function selectProj()
        selIdx = get(lb, 'Value');
        varNamesMapping = get(lb, 'UserData');
        if isempty(varNamesMapping)
            errordlg('Aucun projet shallow n''est disponible.', 'Erreur');
            return;
        end
        % Récupérer le nom de variable correspondant à la sélection
        varName = varNamesMapping{selIdx};
        if isempty(varName)
            errordlg('Veuillez sélectionner un projet valide.', 'Erreur');
            return;
        end
        % Charger l'objet shallow sélectionné depuis l'espace de travail
        proj = evalin('base', varName);
        app.shallowObj = proj;
        displayPositions(app);
        app.Label.Text = fullfile(proj.io.path, [proj.io.file, '.mat']);
        app.TabGroup.SelectedTab = app.LoaddataTab;
        close(dlg);  % Quitter la boîte de dialogue et lever le blocage de uiwait
    end

    function newProj()
        proj = shallowNew;
        if numel(proj) == 0
            return;
        end
        name = proj.io.file;
        assignin('base', name, proj);
        app.shallowObj = proj;
        displayPositions(app);
        app.Label.Text = fullfile(proj.io.path, [proj.io.file, '.mat']);
        app.TabGroup.SelectedTab = app.LoaddataTab;
        close(dlg);
    end

    function cancelPopup()
        close(dlg);
    end


        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)
            % Display a progress dialog
            d = uiprogressdlg(app.LifespanizerUIFigure, 'Title', 'Please Wait...', ...
                'Message', 'Loading selected project...');
            d.Value = 0.33;

            [proj, msg] = shallowLoad;


            % Si le projet est vide, on examine le message
            if isempty(proj)
                if numel(msg)==0
                    close(d)
                    return;
                end

                disp(msg)

                if contains(msg, 'Project is already in the workspace under the var name:')
                    % Extraction du nom de variable à partir du message
                    tokens = regexp(msg, 'Project is already in the workspace under the var name:\s*(.*?)\s*; Quitting\.\.\.', 'tokens');
                    if ~isempty(tokens)
                        projName = tokens{1}{1};  % Extraction du premier élément
                        % Vérifier que la variable existe dans l'espace de travail de base
                        if evalin('base', sprintf('exist(''%s'', ''var'')', projName))
                            proj = evalin('base', projName);
                        else
                            uialert(app.LifespanizerUIFigure, ...
                                'The project variable extracted from the message does not exist in the base workspace.', ...
                                'Error');
                            close(d);
                            return;
                        end
                    else
                        uialert(app.LifespanizerUIFigure, ...
                            'Unable to extract the variable name from the message.', 'Error');
                        close(d);
                        return;
                    end
                elseif contains(msg, 'Wrong file name loaded')
                    uialert(app.LifespanizerUIFigure, 'Wrong file name loaded.', 'Error');
                    close(d);
                    return;
                else
                    uialert(app.LifespanizerUIFigure, sprintf('Unexpected error: %s', msg), 'Error');
                    close(d);
                    return;
                end
            end


            % Vérifier que le projet chargé est bien un objet de la classe shallow
            if ~isa(proj, 'shallow')
                uialert(app.LifespanizerUIFigure, ...
                    'Loaded project is not a valid shallow object.', 'Error');
                close(d);
                return;
            end

            d.Value = 0.66;
            pause(0.2);

            % Affichage du projet dans la console (pour débogage)
            %disp(proj);

            % Récupérer le nom du projet depuis l'objet et l'assigner dans l'espace de travail et dans app.Settings
            name = proj.io.file;
            assignin('base', name, proj);


            app.shallowObj = proj;  % proj est l'objet shallow chargé

            app.TabGroup.SelectedTab = app.LoaddataTab;

            displayPositions(app);

            app.Label.Text= fullfile( proj.io.path, [proj.io.file, '.mat']);


            close(d);

        end

        % Menu selected function: SaveprojectMenu
        function SaveprojectMenuSelected(app, event)
            if ~isempty(app.shallowObj)
                proj=app.shallowObj.io.file;

                shallowObj=evalin('base',proj);

                if numel(shallowObj)==0
                    uialert(app.LifespanizerUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                end

                d = uiprogressdlg(app.LifespanizerUIFigure,'Title','Please Wait...',...
                    'Message','Saving selected project...');
                d.Value=0.1;
                pause(0.5)
                shallowSave(shallowObj,[],d);
                close(d);
            else
                uialert(app.LifespanizerUIFigure,'There is no open project!','Warning','Icon','warning');
            end
        end

        % Menu selected function: CloseprojectMenu
        function CloseprojectMenuSelected(app, event)

        end

        % Cell edit callback: UIPositionTable
        function UIPositionTableCellEdit(app, event)
            indices = event.Indices;  % [row, column]
            c = event.NewData;
            row = indices(1);
            col = indices(2);

            % Pour la colonne 4 ("comment"), mettre à jour le champ correspondant dans le FOV
            if col == 4
                if row <= numel(app.shallowObj.fov)
                    app.shallowObj.fov(row).comments = event.NewData;
                    disp(['FOV ', num2str(row), ' comment updated to: ', event.NewData]);
                else
                    warning('Row index exceeds number of FOV in app.shallowObj.fov');
                end
            end

              if col==11 % viewROI
                tmp=c;

                tmp=str2num(tmp);

                if numel(tmp)

                 roiObj=app.shallowObj.fov(row).roi(tmp(1));

                 roiObj.parent=app.shallowObj.fov(row);

                 figures=findall(0,'Type','figure');
                appFigure=findobj(figures,'Name','ScoreApp');


                if isprop(appFigure,'RunningAppInstance') 
                     appScore= appFigure.RunningAppInstance;
                     appScore.addROI(roiObj);
                else
                       appScore=score(roiObj);
                end

                    if numel(tmp)>1
                        for i=2:numel(tmp)
                         app.shallowObj.fov(row).roi(tmp(i)).parent=app.shallowObj.fov(row);

                        appScore.addROI(app.shallowObj.fov(row).roi(tmp(i)));
                        end
                    end
                end
              end
            

            % (Si besoin, d'autres colonnes éditables (ex. check, #ROIs ou frames)
            % pourraient mettre à jour d'autres propriétés du FOV ici.)

            % Après modification, sauvegarder la table mise à jour dans le pipeline
            app.shallowObj.processing.pipeline.positionTable = app.UIPositionTable.Data;


        end

        % Selection changed function: UIPositionTable
        function UIPositionTableSelectionChanged(app, event)
            selection = app.UIPositionTable.Selection;
            if isempty(selection)
                return;
            end
            % Extraction des indices [row, column] de la première sélection
            selectedRow = selection(1);
            selectedCol = selection(2);

            % Supposons que la colonne "open" est la 9ème colonne
            openColumnIndex = 10;

            if selectedCol == openColumnIndex
                % Si la colonne "open" est sélectionnée, lancer la visualisation en mode 'preview'
                if ~isempty(app.shallowObj)

                    loadData(app.shallowObj, selectedRow);
                else
                    uialert(app.LifespanizerUIFigure, 'No shallow project loaded.', 'Error');
                end
            else
                % Autrement, mettre à jour la table des channels pour la position sélectionnée
                %   updateChannelTable(app, selectedRow);
            end

            if selectedCol==11 % viewROI
                tmp=app.UIPositionTable.Data{selectedRow,11};

                tmp=str2num(tmp);

                if numel(tmp)
                 roiObj=app.shallowObj.fov(selectedRow).roi(tmp(1));

                 roiObj.parent=app.shallowObj.fov(selectedRow);

                 figures=findall(0,'Type','figure');
                appFigure=findobj(figures,'Name','ScoreApp');
              

                if isprop(appFigure,'RunningAppInstance') 
                      appScore= appFigure.RunningAppInstance;
                     appScore.addROI(roiObj);
                else
                       appScore=score(roiObj);
                end


                    if numel(tmp)>1
                        for i=2:numel(tmp)
                             app.shallowObj.fov(selectedRow).roi(tmp(i)).parent=app.shallowObj.fov(selectedRow);
                             appScore.addROI(app.shallowObj.fov(selectedRow).roi(tmp(i)));
                       
                        end
                    end
                end
            end

        end

        % Button pushed function: EditpreprocessingparametersButton
        function EditpreprocessingparametersButtonPushed(app, event)

            processObj= getProcessorByName(app, 'CombineChannel');
            processGUI(processObj,app);
            uiwait(app.LifespanizerUIFigure);


            params=processObj.processArg;
            paramStr = evalc('disp(params)');
            app.ParametersLabel.Text = paramStr;
lifespan_

        end

        % Button down function: PreprocessingTab
        function PreprocessingTabButtonDown(app, event)

            %% find or setup processor

            if isempty(app.shallowObj)
                uialert(app.LifespanizerUIFigure, 'No shallow project loaded. First load or create  a project !', 'Error');
                return;
            end

            processObj=lifespan_setupPreProcessing(app.shallowObj, 'CombineChannel',7); % 7 is the index for the combine channel processor

           % app.shallowObj=shallowObj;
            %   app.processObj=processObj;

            params=processObj.processArg;

            paramStr = evalc('disp(params)');
            app.ParametersLabel.Text = paramStr;


            displayPositions(app)

        end

        % Button pushed function: setclassifierButton
        function setclassifierButtonPushed(app, event)
            classiRepository(app);

            uiwait(app.LifespanizerUIFigure);

            pth=app.LifespanizerUIFigure.UserData;

            classiFromRepository=classiLoad(pth);

            if numel(classiFromRepository)==0
                return;
            end

            if ~strcmp(classiFromRepository.category{1},'LSTM')
                uialert(app.LifespanizerUIFigure, 'No CNN+LSTM classifier was selected  !', 'Error');
                return;
            end

            % gathering transfer options
            option=[];
            option(1)=true; % trainign parameters
            option(2)=true; %trained classifier
            option(3)=false; % image dataset
            option(4)=false; % rois


            % Vérifier s'il existe déjà un classifier portant le nom "divisionClassifier" (avec suffixe optionnel)
            existingClassi = getClassificationByName(app, 'divisionClassifier');
            if ~isempty(existingClassi)
                % Si un classifier existant est trouvé, le supprimer de la collection
                idx = find(arrayfun(@(c) strcmp(c.strid, existingClassi.strid), app.shallowObj.processing.classification));
                if ~isempty(idx)
                    app.shallowObj.processing.classification(idx) = [];
                end
            end


            app.shallowObj.addClassification('name','divisionClassifier');

            classiObj = getClassificationByName(app, 'divisionClassifier');


            d.Value=0.3;
            d.Message='Importing selected ROIs.... Please wait...';


            option2=option;
            option2(4)=0; % roi iport should not be done  with importFromClassi
            classiObj.importFromClassi( classiFromRepository,option2);
            classiObj.channelName={'CombinedChannel'};

            app.DivClassifierLabel.Text=classiObj.description;

            app.shallowObj.processing.pipeline.divisionClassifier=classiObj.strid;


        end

        % Button pushed function: setclassifierButton_2
        function setclassifierButton_2Pushed(app, event)
            classiRepository(app);

            uiwait(app.LifespanizerUIFigure);

            pth=app.LifespanizerUIFigure.UserData;

            classiFromRepository=classiLoad(pth);

              if numel(classiFromRepository)==0
                return;
            end

            if ~strcmp(classiFromRepository.category{1},'Pixel')
                uialert(app.LifespanizerUIFigure, 'No Pixel classifier was selected  !', 'Error');
                return;
            end

            % gathering transfer options
            option=[];
            option(1)=true; % trainign parameters
            option(2)=true; %trained classifier
            option(3)=false; % image dataset
            option(4)=false; % rois

            % Vérifier s'il existe déjà un classifier portant le nom "divisionClassifier" (avec suffixe optionnel)
            existingClassi = getClassificationByName(app, 'segcellClassifier');
            if ~isempty(existingClassi)
                % Si un classifier existant est trouvé, le supprimer de la collection
                idx = find(arrayfun(@(c) strcmp(c.strid, existingClassi.strid), app.shallowObj.processing.classification));
                if ~isempty(idx)
                    app.shallowObj.processing.classification(idx) = [];
                end
            end

            app.shallowObj.addClassification('name','cellsegClassifier');

            classiObj = getClassificationByName(app, 'cellsegClassifier');

            d.Value=0.3;
            d.Message='Importing selected ROIs.... Please wait...';

            option2=option;
            option2(4)=0; % roi iport should not be done  with importFromClassi
            classiObj.importFromClassi( classiFromRepository,option2);

            processObj=app.getProcessorByName('CombineChannel');
            procArg =processObj.processArg;  % ou, par exemple: myprojectlifespan.processing.processor(1).processArg;
            % Définir la couleur cible
            targetColor = [0 1 0];

            % Récupérer tous les noms de champs de procArg
            fieldNames = fieldnames(procArg);

            foundChannel = '';  % variable pour stocker le nom trouvé

            % Parcourir les champs
            for i = 1:length(fieldNames)
                currentField = fieldNames{i};
                % On teste uniquement les champs dont le nom commence par "RGB_"
                if startsWith(currentField, 'RGB_')
                    % Récupérer la valeur associée à ce champ
                    colorValue = procArg.(currentField);
                    % Vérifier si la valeur est exactement [0 1 0]
                    if isequal(colorValue, targetColor)
                        % Extraire le nom du channel en retirant le préfixe "RGB_"
                        foundChannel = currentField(5:end);
                        break;  % sortir de la boucle dès qu'une correspondance est trouvée
                    end
                end
            end

            % Affichage du résultat
            if ~isempty(foundChannel)
                fprintf('Le channel trouvé avec RGB [0 1 0] est : %s\n', foundChannel);
            else
                fprintf('Aucun channel avec RGB [0 1 0] n''a été trouvé.\n');
            end


            classiObj.outputType= 'postprocessing';
            classiObj.outputFun='post';
            classiObj.outputArg= {'threshold'  '0.9'  'watershed'  'sizethreshold'  '10'};
            classiObj.channelName={foundChannel};

            app.CellClassifierLabel.Text=classiObj.description;

            app.shallowObj.processing.pipeline.cellsegClassifier=classiObj.strid;

        end

        % Button pushed function: SetRLSButton
        function SetRLSButtonPushed(app, event)

            classiObj=app.getClassificationByName('divisionClassifier');
            if numel(classiObj)==0
                uialert(app.LifespanizerUIFigure, 'Please set the division classifier first  !', 'Error');
                return;
            end

            processObj= getProcessorByName(app, 'ComputeRLS')
            processGUI(processObj,app);
            uiwait(app.LifespanizerUIFigure);
            params=processObj.processArg;
            paramStr = evalc('disp(params)');
            app.RLSLabel.Text = paramStr;

            processObj.processArg.classification_data={classiObj.strid classiObj.strid};


            app.shallowObj.processing.pipeline.RLSProcessor= processObj.strid;

        end

        % Button pushed function: CellmetricsButton
        function CellmetricsButtonPushed2(app, event)

            classiObj=app.getClassificationByName('cellsegClassifier');
            if numel(classiObj)==0
                uialert(app.LifespanizerUIFigure, 'Please set the cell segmentation classifier first  !', 'Error');
                return;
            end

            processObj= getProcessorByName(app, 'ComputeAllMetrics');
            processGUI(processObj,app);
            uiwait(app.LifespanizerUIFigure);

            params=processObj.processArg;
            paramStr = evalc('disp(params)');
            app.MetricsLabel.Text = paramStr;

        %    processObj.processArg.classification_data={['results_' classiObj.strid] ['results_' classiObj.strid]};
            processObj.processArg.mask1_name={['results_' classiObj.strid] ['results_' classiObj.strid]};
            processObj.processArg.mask1_stat=true;
            processObj.processArg.mask2_stat=false;

            app.shallowObj.processing.pipeline.cellmetricsProcessor= processObj.strid;

        end

        % Button down function: ClassifyTab
        function ClassifyTabButtonDown(app, event)
            if isempty(app.shallowObj)
                uialert(app.LifespanizerUIFigure, 'No shallow project loaded. First load or create  a project !', 'Error');
                return;
            end


            % 7 is the index for the combine channel processor
            processObj=lifespan_setupPreProcessing(app.shallowObj, 'ComputeRLS',13);

           % app.shallowObj=shallowObj;

            params=processObj.processArg;

            paramStr = evalc('disp(params)');
            app.RLSLabel.Text = paramStr;


            processObj=lifespan_setupPreProcessing(app.shallowObj, 'ComputeAllMetrics',12);
            % 7 is the index for the combine channel processor

           % app.shallowObj=shallowObj;
            %   app.processObj=processObj;

            params=processObj.processArg;

            paramStr = evalc('disp(params)');
            app.MetricsLabel.Text = paramStr;


            % update classifier param display

            if isfield(app.shallowObj.processing.pipeline,'divisionClassifier')
                classiObj=app.getClassificationByName(app.shallowObj.processing.pipeline.divisionClassifier);
                app.DivClassifierLabel.Text=classiObj.description;
            else
app.DivClassifierLabel.Text='';
            end
            if isfield(app.shallowObj.processing.pipeline,'cellsegClassifier')
                classiObj=app.getClassificationByName(app.shallowObj.processing.pipeline.cellsegClassifier);
                app.CellClassifierLabel.Text=classiObj.description;
            else
 app.CellClassifierLabel.Text='';
            end

            if isfield(app.shallowObj.processing.pipeline,'postproc')
                app.postprocessingCheckBox.Value = app.shallowObj.processing.pipeline.postproc;

            end
            if isfield(app.shallowObj.processing.pipeline,'cellseg')
                app.cellsegmentationCheckBox.Value = app.shallowObj.processing.pipeline.cellseg;
            end
            if isfield(app.shallowObj.processing.pipeline,'division')
                app.divisionclassifierCheckBox.Value = app.shallowObj.processing.pipeline.division;
            end


            displayPositions(app)


        end

        % Value changed function: divisionclassifierCheckBox
        function divisionclassifierCheckBoxValueChanged(app, event)
            value = app.divisionclassifierCheckBox.Value;
            app.shallowObj.processing.pipeline.division= value;
        end

        % Value changed function: cellsegmentationCheckBox
        function cellsegmentationCheckBoxValueChanged(app, event)
            value = app.cellsegmentationCheckBox.Value;
            app.shallowObj.processing.pipeline.cellseg= value;

        end

        % Value changed function: postprocessingCheckBox
        function postprocessingCheckBoxValueChanged(app, event)
            value = app.postprocessingCheckBox.Value;
            app.shallowObj.processing.pipeline.postproc= value;
        end

        % Button down function: ExportTab
        function ExportTabButtonDown(app, event)
           
            % Vérifier que l'objet shallowObj est chargé et contient des FOV
    if isempty(app.shallowObj) || ~isprop(app.shallowObj, 'fov') || isempty(app.shallowObj.fov)
        uialert(app.LifespanizerUIFigure, 'No FOV data available in the project.', 'Error');
        return;
    end

    % Réglage du répertoire d'export XLSX
    if isfield(app.shallowObj.processing.pipeline, 'exportdir')
        app.DatasheetexportfilexlsxEditField.Value = app.shallowObj.processing.pipeline.exportdir;
    else
        str = [app.shallowObj.io.path, filesep];
        app.DatasheetexportfilexlsxEditField.Value = str;
        app.shallowObj.processing.pipeline.exportdir = str;
    end

    fovs = app.shallowObj.fov;
    nFOV = numel(fovs);

    % Récupérer la table des positions déjà affichée
    posTable = app.UIPositionTable.Data;
    % Sélectionner les lignes où la première colonne (check) est vraie
    selectedRows = find(cell2mat(posTable(:,1)));
    nSelected = numel(selectedRows);

    % Préparer un tableau de structures datagroups pour les FOV sélectionnés
    datagroups = repmat(struct('Name', '', 'Data', struct(), 'Param', struct(), 'Table', [], 'Source', struct(), 'Type', '', 'tip', {}), nSelected, 1);

    for k = 1:nSelected
        i = selectedRows(k);  % indice du FOV sélectionné
        fovObj = fovs(i);
        
        % Utiliser directement le nom du FOV
        if isprop(fovObj, 'id') && ~isempty(fovObj.id)
            datagroups(k).Name = fovObj.id;
        else
            datagroups(k).Name = ['FOV' num2str(i)];
        end

        % Récupérer le tableau de ROI de ce fov
        if isprop(fovObj, 'roi') && ~isempty(fovObj.roi)
            roiObjs = fovObj.roi;
        else
            roiObjs = [];
        end

        % Construire la liste des nodeid à partir de chaque ROI
        nodeids = cell(numel(roiObjs), 1);
        for j = 1:numel(roiObjs)
            if isprop(roiObjs(j), 'id')
                nodeids{j} = roiObjs(j).id;
            else
                nodeids{j} = '';
            end
        end

        % Remplir le champ Data (on suppose que le chemin du projet est stocké dans app.shallowObj.io.file)
        if isprop(app.shallowObj.io, 'file')
            filepath = app.shallowObj.io.file;
        else
            filepath = '';
        end

        datagroups(k).Data = struct('nodeid', {nodeids}, 'roiobj', roiObjs, 'filepath', {{filepath}}, 'type', {{'shallow'}});
       % datagroups(k).Param = struct();
        datagroups(k).Table = [];

        % Générer le champ Source : on crée une structure avec 2 champs, nodeid et nodename.
        % Pour nodeid : on récupère la liste des channels du premier ROI (si existante).
        % Pour nodename : pour chaque channel, on crée un cell array {output, type} où
        % "output" est le nom défini dans app.processObj.processArg.outputChannelName (s'il existe)
        % et "type" est déterminé en fonction du nom du channel.
       
         datagroups(k).Source.nodeid = {'Results1_3' 'Results1_4'};
         classiObj=app.getClassificationByName('divisionClassifier');
         datagroups(k).Source.nodename{1} = [{['RLS_' classiObj.strid]} {'divduration'}];
         datagroups(k).Source.nodename{2} = [{['RLS_' classiObj.strid]} {'event'}];

         datagroups(k).Param.Traj_synchronization={'birth'};
         datagroups(k).Param.tip=[];

        % Type : 'generation' par défaut (modifiable selon vos besoins)
        datagroups(k).Type = 'generation';
        datagroups(k).tip = {};
    end

    % Stocker le résultat dans l'app pour usage ultérieur et dans l'espace de travail
    app.Data.datagroups = datagroups;
    assignin('base', 'datagroups', datagroups);

        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
        groups=evalin('base','datagroups');
        filename=app.DatasheetexportfilexlsxEditField.Value;
         plotRLS(groups,filename);
         plotDivisionTimes(groups,filename);
       %  plotData_generic(groups,filename)
        end

        % Button pushed function: SetxlsxexportpathButton
        function SetxlsxexportpathButtonPushed(app, event)
             % Ouvre une boîte de dialogue pour sélectionner un dossier d'export XLSX
         folder = uigetdir('', 'Select XLSX Export Directory');
    
    % Si l'utilisateur annule (folder == 0), on quitte la fonction
    if folder == 0
        return;
    end
    
    % Met à jour l'edit field avec le chemin sélectionné
    app.DatasheetexportfilexlsxEditField.Value = folder;
     app.shallowObj.processing.pipeline.exportdir=folder;
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
               % Récupère les données de la table
    tableData = app.UIPositionTable.Data;
    if isempty(tableData)
        return;
    end
    % Parcourir toutes les lignes et mettre la première colonne à true
    for i = 1:size(tableData, 1)
        tableData{i, 1} = true;
    end
    % Mettre à jour la table affichée
    app.UIPositionTable.Data = tableData;
    % Mettre à jour le pipeline si nécessaire
    if isfield(app.shallowObj.processing.pipeline, 'positionTable')
        app.shallowObj.processing.pipeline.positionTable = tableData;
    end
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
                % Récupère les données de la table
    tableData = app.UIPositionTable.Data;
    if isempty(tableData)
        return;
    end
    % Parcourir toutes les lignes et mettre la première colonne à false
    for i = 1:size(tableData, 1)
        tableData{i, 1} = false;
    end
    % Mettre à jour la table affichée
    app.UIPositionTable.Data = tableData;
    % Mettre à jour le pipeline si nécessaire
    if isfield(app.shallowObj.processing.pipeline, 'positionTable')
        app.shallowObj.processing.pipeline.positionTable = tableData;
    end
        end

        % Button pushed function: ResetROIsselectionButton
        function ResetROIsselectionButtonPushed(app, event)
             % Vérifier que l'objet shallowObj existe et contient des FOV
    if isempty(app.shallowObj) || ~isprop(app.shallowObj, 'fov') || isempty(app.shallowObj.fov)
        uialert(app.LifespanizerUIFigure, 'No FOV data available.', 'Error');
        return;
    end

    fovs = app.shallowObj.fov;
    nFOV = numel(fovs);
    
    % Récupérer la table actuelle ; si le nombre de lignes ne correspond pas, la réinitialiser
    tableData = app.UIPositionTable.Data;
    if isempty(tableData) || size(tableData,1) ~= nFOV
        tableData = cell(nFOV, 11);
    end
    
    % Pour chaque FOV, mettre à jour la colonne "#ROIs" (colonne 5)
    for i = 1:nFOV
        if isprop(fovs(i), 'roi') && ~isempty(fovs(i).roi) && isprop(fovs(i).roi(1), 'id')
            roiCount = numel(fovs(i).roi);
            tableData{i,5} = ['1:' num2str(roiCount)];
        else
            tableData{i,5} = '0';
        end
    end

    % Mettre à jour la table affichée
    app.UIPositionTable.Data = tableData;
    
    % Optionnel : sauvegarder la table mise à jour dans le pipeline
    if isfield(app.shallowObj.processing.pipeline, 'positionTable')
        app.shallowObj.processing.pipeline.positionTable = tableData;
    end
        end

        % Button pushed function: ResetframeselectionButton
        function ResetframeselectionButtonPushed(app, event)
             % Vérifier que l'objet shallowObj et ses FOV existent
    if isempty(app.shallowObj) || ~isprop(app.shallowObj, 'fov') || isempty(app.shallowObj.fov)
        uialert(app.LifespanizerUIFigure, 'No field-of-view data available in the project.', 'Error');
        return;
    end

    fovs = app.shallowObj.fov;
    nFOV = numel(fovs);

    % Récupérer la table actuelle ; si le nombre de lignes ne correspond pas, la réinitialiser
    tableData = app.UIPositionTable.Data;
    if isempty(tableData) || size(tableData,1) ~= nFOV
        tableData = cell(nFOV, 11);
    end

    % On suppose que l'objet shallowObj contient les positions analysées
    parsedPositions = [];
    if isprop(app.shallowObj, 'parsedData') && isfield(app.shallowObj.parsedData, 'positions')
        parsedPositions = app.shallowObj.parsedData.positions;
    end

    % Pour chaque FOV, recalculer la chaîne de frames par défaut et la stocker dans la colonne 6
    for i = 1:nFOV
        framesStr = 'N/A';
        if ~isempty(parsedPositions)
            if isprop(fovs(i), 'id')
                rawFovID = fovs(i).id;
                modifiedFovID = regexprep(rawFovID, '_\d+', '');
            else
                modifiedFovID = sprintf('FOV%d', i);
            end
            posFound = false;
            for k = 1:numel(parsedPositions)
                if isfield(parsedPositions(k), 'userName') && strcmpi(parsedPositions(k).userName, modifiedFovID)
                    if isfield(parsedPositions(k), 'currentMinFrame') && isfield(parsedPositions(k), 'currentMaxFrame')
                        framesStr = sprintf('%d:%d', parsedPositions(k).currentMinFrame, parsedPositions(k).currentMaxFrame);
                    else
                        framesStr = 'N/A';
                    end
                    posFound = true;
                    break;
                end
            end
            if ~posFound
                framesStr = 'N/A';
            end
        end
        tableData{i,6} = framesStr;
    end

    % Mettre à jour la table UIPositionTable
    app.UIPositionTable.Data = tableData;

    % Mettre à jour le pipeline si nécessaire
    if isfield(app.shallowObj.processing.pipeline, 'positionTable')
        app.shallowObj.processing.pipeline.positionTable = tableData;
    end
        end

        % Button pushed function: applysettingstoallpositionsButton
        function applysettingstoallpositionsButtonPushed(app, event)
             % Récupérer les données actuelles de la table
    tableData = app.UIPositionTable.Data;
    if isempty(tableData)
        return;
    end
    
    % Vérifier qu'une ligne est sélectionnée
    sel = app.UIPositionTable.Selection;
    if isempty(sel)
        uialert(app.LifespanizerUIFigure, 'Please select a row first.', 'Error');
        return;
    end
    selectedRow = sel(1);
    
    % Extraire les valeurs de la ligne sélectionnée
    % On suppose que la colonne 1 est "check", la colonne 5 est "#ROIs" et la colonne 6 est "frames"
    selCheck = tableData{selectedRow, 1};
    selROIs  = tableData{selectedRow, 5};
    selFrames = tableData{selectedRow, 6};
    
    % Appliquer ces valeurs à toutes les lignes de la table
    nRows = size(tableData, 1);
    for i = 1:nRows
        tableData{i, 1} = selCheck;
        tableData{i, 5} = selROIs;
        tableData{i, 6} = selFrames;
    end
    
    % Mettre à jour la table affichée
    app.UIPositionTable.Data = tableData;
    
    % Mettre à jour le pipeline si nécessaire
    if isfield(app.shallowObj.processing.pipeline, 'positionTable')
        app.shallowObj.processing.pipeline.positionTable = tableData;
    end
        end

        % Menu selected function: SetclassifierrepositorypathMenu
        function SetclassifierrepositorypathMenuSelected(app, event)
            % Ouvrir une boîte de dialogue pour sélectionner un dossier
    newPath = uigetdir('', 'Select Repository Path');
    if newPath == 0
        % L'utilisateur a annulé la sélection
        return;
    end

    % Récupérer le userpath et définir le dossier Detecdiv
    basePath = userpath;
    detecdivFolder = fullfile(basePath, 'Detecdiv');
    if ~exist(detecdivFolder, 'dir')
        mkdir(detecdivFolder);
    end


    % Définir le chemin complet du fichier repositoryPath.mat
    repoFile = fullfile(detecdivFolder, 'repositoryPath.mat')

    % Stocker le nouveau chemin dans la variable repopth
    repopth = newPath;
    save(repoFile, 'repopth');  % Sauvegarder dans le fichier .mat

    % Mettre à jour l'affichage dans l'app
    app.RepositoryPathLabel.Text = repopth;
        end

        % Menu selected function: erasedataMenu
        function erasedataMenuSelected(app, event)
        % Retrieve the table data
    tableData = app.UIPositionTable.Data;
    if isempty(tableData)
        uialert(app.LifespanizerUIFigure, 'No positions available in the table.', 'Error');
        return;
    end
    
    % Find rows where the first column (checkbox) is true
    selectedRows = find(cellfun(@(x) isequal(x, true), tableData(:,1)));
    if isempty(selectedRows)
        uialert(app.LifespanizerUIFigure, 'No positions selected.', 'Error');
        return;
    end
    
    % Ask for confirmation from the user
    promptMsg = sprintf('Are you sure you want to delete %d selected positions?', numel(selectedRows));
    choice = uiconfirm(app.LifespanizerUIFigure, promptMsg, 'Confirm Deletion', ...
        'Options', {'Yes','No'}, 'DefaultOption', 2);
    if ~strcmp(choice, 'Yes')
        return;
    end
    
    % Remove the selected FOVs from app.shallowObj.fov
    allFOVs = app.shallowObj.fov;
    % Create a new array excluding the FOVs at indices in selectedRows
    remainingFOVs = allFOVs;
    remainingFOVs(selectedRows) = [];
    app.shallowObj.fov = remainingFOVs;

       % Remove corresponding positions from parsedData (if they exist)
    if isfield(app.shallowObj, 'parsedData') && isfield(app.shallowObj.parsedData, 'positions')
        positions = app.shallowObj.parsedData.positions;
        % Ensure positions is a cell or array that supports deletion by index
        if numel(positions) >= max(selectedRows)
            positions(selectedRows) = [];
            app.shallowObj.parsedData.positions = positions;
        end
    end
    
    % Remove the corresponding rows from the table data
    newTableData = tableData;
    newTableData(selectedRows, :) = [];
    app.UIPositionTable.Data = newTableData;
    
    % Update the pipeline position table if it exists
    if isfield(app.shallowObj.processing.pipeline, 'positionTable')
        app.shallowObj.processing.pipeline.positionTable = newTableData;
    end
    
    % Notify the user of successful deletion
    uialert(app.LifespanizerUIFigure, 'Selected positions have been deleted.', 'Deletion Complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LifespanizerUIFigure = uifigure('Visible', 'off');
            app.LifespanizerUIFigure.Name = 'Lifespanizer';
            app.FileMenu = uimenu(app.LifespanizerUIFigure);
            app.FileMenu.Text = 'File';
            app.CreatenewprojectorselectprojectinworkspaceMenu = uimenu(app.FileMenu);
            app.CreatenewprojectorselectprojectinworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatenewprojectorselectprojectinworkspaceMenuSelected, true);
            app.CreatenewprojectorselectprojectinworkspaceMenu.Text = 'Create new project or select project in workspace...';
            app.OpenprojectMenu = uimenu(app.FileMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project...';
            app.SaveprojectMenu = uimenu(app.FileMenu);
            app.SaveprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectMenuSelected, true);
            app.SaveprojectMenu.Text = 'Save project';
            app.CloseprojectMenu = uimenu(app.FileMenu);
            app.CloseprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectMenuSelected, true);
            app.CloseprojectMenu.Text = 'Close project';
            app.SetclassifierrepositorypathMenu = uimenu(app.FileMenu);
            app.SetclassifierrepositorypathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetclassifierrepositorypathMenuSelected, true);
            app.SetclassifierrepositorypathMenu.Separator = 'on';
            app.SetclassifierrepositorypathMenu.Text = 'Set classifier repository path...';
            app.erasedataMenu = uimenu(app.FileMenu);
            app.erasedataMenu.MenuSelectedFcn = createCallbackFcn(app, @erasedataMenuSelected, true);
            app.erasedataMenu.Separator = 'on';
            app.erasedataMenu.Text = 'erase data...';
            app.UIPositionTable = uitable(app.LifespanizerUIFigure);
            app.UIPositionTable.ColumnName = {'check'; 'id'; 'name'; 'comment'; '#ROIs'; 'frames'; 'extracted'; 'preP'; 'classified'; 'FOV'; 'view ROI'};
            app.UIPositionTable.RowName = {};
            app.UIPositionTable.ColumnEditable = [true false false true true true false false false false true true true];
            app.UIPositionTable.CellEditCallback = createCallbackFcn(app, @UIPositionTableCellEdit, true);
            app.UIPositionTable.SelectionChangedFcn = createCallbackFcn(app, @UIPositionTableSelectionChanged, true);
            app.Label = uilabel(app.LifespanizerUIFigure);
            app.TabGroup = uitabgroup(app.LifespanizerUIFigure);
            app.LoaddataTab = uitab(app.TabGroup);
            app.LoaddataTab.Title = 'Load data';
            app.LoadAdddataButton = uibutton(app.LoaddataTab, 'push');
            app.LoadAdddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAdddataButtonPushed, true);
            app.LoadAdddataButton.Text = 'Load/Add data';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre processing';
            app.PreprocessingTab.ButtonDownFcn = createCallbackFcn(app, @PreprocessingTabButtonDown, true);
            app.RunpreprocessingButton = uibutton(app.PreprocessingTab, 'push');
            app.RunpreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunpreprocessingButtonPushed, true);
            app.RunpreprocessingButton.Text = 'Run pre-processing';
            app.CombinemultiplezstackintoasinglechannelLabel = uilabel(app.PreprocessingTab);
            app.CombinemultiplezstackintoasinglechannelLabel.Text = 'Combine multiple z-stack into a single channel';
            app.EditpreprocessingparametersButton = uibutton(app.PreprocessingTab, 'push');
            app.EditpreprocessingparametersButton.ButtonPushedFcn = createCallbackFcn(app, @EditpreprocessingparametersButtonPushed, true);
            app.EditpreprocessingparametersButton.Text = 'Edit preprocessing parameters...';
            app.ParametersLabel = uilabel(app.PreprocessingTab);
            app.ParametersLabel.Text = 'Parameters';
            app.ClassifyTab = uitab(app.TabGroup);
            app.ClassifyTab.Title = 'Classify';
            app.ClassifyTab.ButtonDownFcn = createCallbackFcn(app, @ClassifyTabButtonDown, true);
            app.ClassifyProcessButton = uibutton(app.ClassifyTab, 'push');
            app.ClassifyProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyProcessButtonPushed, true);
            app.ClassifyProcessButton.Text = 'Classify & Process';
            app.divisionclassifierCheckBox = uicheckbox(app.ClassifyTab);
            app.divisionclassifierCheckBox.ValueChangedFcn = createCallbackFcn(app, @divisionclassifierCheckBoxValueChanged, true);
            app.divisionclassifierCheckBox.Text = 'division classifier';
            app.cellsegmentationCheckBox = uicheckbox(app.ClassifyTab);
            app.cellsegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @cellsegmentationCheckBoxValueChanged, true);
            app.cellsegmentationCheckBox.Text = 'cell segmentation';
            app.postprocessingCheckBox = uicheckbox(app.ClassifyTab);
            app.postprocessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @postprocessingCheckBoxValueChanged, true);
            app.postprocessingCheckBox.Text = 'post-processing ';
            app.setclassifierButton = uibutton(app.ClassifyTab, 'push');
            app.setclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @setclassifierButtonPushed, true);
            app.setclassifierButton.Text = 'set classifier...';
            app.setclassifierButton_2 = uibutton(app.ClassifyTab, 'push');
            app.setclassifierButton_2.ButtonPushedFcn = createCallbackFcn(app, @setclassifierButton_2Pushed, true);
            app.setclassifierButton_2.Text = 'set classifier...';
            app.CellClassifierLabel = uilabel(app.ClassifyTab);
            app.CellClassifierLabel.Text = 'CellClassifier';
            app.SetRLSButton = uibutton(app.ClassifyTab, 'push');
            app.SetRLSButton.ButtonPushedFcn = createCallbackFcn(app, @SetRLSButtonPushed, true);
            app.SetRLSButton.Text = 'Set RLS...';
            app.CellmetricsButton = uibutton(app.ClassifyTab, 'push');
            app.CellmetricsButton.ButtonPushedFcn = createCallbackFcn(app, @CellmetricsButtonPushed2, true);
            app.CellmetricsButton.Text = 'Cell metrics...';
            app.RLSLabel = uilabel(app.ClassifyTab);
            app.RLSLabel.Text = 'RLS';
            app.MetricsLabel = uilabel(app.ClassifyTab);
            app.MetricsLabel.Text = 'Metrics';
            app.DivClassifierLabel = uilabel(app.ClassifyTab);
            app.DivClassifierLabel.Text = 'DivClassifier';
            app.RepositoryPathLabel = uilabel(app.ClassifyTab);
            app.RepositoryPathLabel.Text = 'RepositoryPath';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportTab.ButtonDownFcn = createCallbackFcn(app, @ExportTabButtonDown, true);
            app.ExportDataButton = uibutton(app.ExportTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.PlotDataButton = uibutton(app.ExportTab, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.DatasheetexportfilexlsxLabel = uilabel(app.ExportTab);
            app.DatasheetexportfilexlsxLabel.Text = 'Data sheet export file xlsx';
            app.DatasheetexportfilexlsxEditField = uieditfield(app.ExportTab, 'text');
            app.SetxlsxexportpathButton = uibutton(app.ExportTab, 'push');
            app.SetxlsxexportpathButton.ButtonPushedFcn = createCallbackFcn(app, @SetxlsxexportpathButtonPushed, true);
            app.SetxlsxexportpathButton.Text = 'Set xlsx export path...';
            app.SelectallButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.ResetROIsselectionButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.ResetROIsselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIsselectionButtonPushed, true);
            app.ResetROIsselectionButton.Text = 'Reset ROIs selection';
            app.ResetframeselectionButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.ResetframeselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetframeselectionButtonPushed, true);
            app.ResetframeselectionButton.Text = 'Reset frame selection';
            app.applysettingstoallpositionsButton = uibutton(app.LifespanizerUIFigure, 'push');
            app.applysettingstoallpositionsButton.ButtonPushedFcn = createCallbackFcn(app, @applysettingstoallpositionsButtonPushed, true);
            app.applysettingstoallpositionsButton.Text = 'apply settings to all positions';
            % Show the figure after all components are created
            app.LifespanizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lifespan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LifespanizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LifespanizerUIFigure)
        end
    end
end