classdef loadData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadingdataUIFigure         matlab.ui.Figure
        LoadButton                  matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        ImagesTab                   matlab.ui.container.Tab
        InfoLabel                   matlab.ui.control.Label
        PathLabel                   matlab.ui.control.Label
        ChoosefilesButton           matlab.ui.control.Button
        ORLabel                     matlab.ui.control.Label
        PositionsPanel              matlab.ui.container.Panel
        UIPositionTable             matlab.ui.control.Table
        ChannelsPanel               matlab.ui.container.Panel
        applychangestoallpositionsCheckBox  matlab.ui.control.CheckBox
        FramesField                 matlab.ui.control.EditField
        FramesSlider                matlab.ui.control.RangeSlider
        FramesSliderLabel           matlab.ui.control.Label
        UIChannelTable              matlab.ui.control.Table
        InvertframesandpositionsCheckBox  matlab.ui.control.CheckBox
        AdvancedmodeCheckBox        matlab.ui.control.CheckBox
        ChoosefolderButton          matlab.ui.control.Button
        ProjectTab                  matlab.ui.container.Tab
        SetProjectButton            matlab.ui.control.Button
        OutputprojectfileEditField  matlab.ui.control.EditField
        OutputprojectfileEditFieldLabel  matlab.ui.control.Label
        ROIsTab                     matlab.ui.container.Tab
        PositionSlider              matlab.ui.control.Slider
        PositionSliderLabel         matlab.ui.control.Label
        ChannelSlider               matlab.ui.control.Slider
        ChannelSliderLabel          matlab.ui.control.Label
        FramesSlider_2              matlab.ui.control.Slider
        FramesSlider_2Label         matlab.ui.control.Label
        ROIsButtonGroup             matlab.ui.container.ButtonGroup
        correctdriftCheckBox        matlab.ui.control.CheckBox
        DimensionsEditField         matlab.ui.control.EditField
        DimensionsEditFieldLabel    matlab.ui.control.Label
        FindallButton               matlab.ui.control.Button
        CreatROIButton              matlab.ui.control.Button
        ROIDivide                   matlab.ui.control.NumericEditField
        CustomROIsButton            matlab.ui.control.RadioButton
        DivideframeintoButton       matlab.ui.control.RadioButton
        FullframeROIsButton         matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
        CancelButton                matlab.ui.control.Button
    end


    properties (Access = public)
        parsedData % Description
    end

    methods (Access = public)

 
function updatePositionTable(app)
    % Met à jour la table des positions et le slider à partir de app.parsedData.
    
    positions = app.parsedData.positions;  % Array de structures
    nPos = numel(positions);
    posTableData = cell(nPos, 3);  % Colonnes: {Select, File Pos Name, User Name}
    
    for i = 1:nPos
        % Extraire le nom du dossier à partir du champ folder (pour File Pos Name)
        if isfield(positions(i), 'folder')
            folderValue = positions(i).folder;
            if ischar(folderValue) && ~strcmp(folderValue, '')
                folderPath = folderValue;
            elseif isstring(folderValue) && folderValue ~= ""
                folderPath = char(folderValue);
            else
                folderPath = '';
            end
        else
            folderPath = '';
        end

        if ~isempty(folderPath)
            % Retirer le séparateur final s'il existe
            if folderPath(end)==filesep || folderPath(end)=='/'
                folderPath = folderPath(1:end-1);
            end
            % Extraire le nom du dossier (après le dernier séparateur)
            idx = find(folderPath==filesep | folderPath=='/', 1, 'last');
            if isempty(idx)
                filePosName = folderPath;
            else
                filePosName = folderPath(idx+1:end);
            end
        else
            filePosName = sprintf('Pos%d', i-1);
        end

        % Utiliser le champ 'selected' et 'userName' déjà stocké dans la structure
        posTableData{i, 1} = positions(i).selected;

        if app.InvertframesandpositionsCheckBox.Value==false
        posTableData{i, 2} = filePosName;  
        else
        posTableData{i, 2} ='AllPositions';  
        end
        posTableData{i, 3} = positions(i).userName;
    end
    
    app.UIPositionTable.Data = posTableData;
    
    % Mise à jour par défaut du slider des frames pour la première position
    % (ou pour une position par défaut, ici la première)
    posData = positions(1);
    app.FramesSlider.Limits = [posData.minFrame, posData.maxFrame];
    if isfield(posData, 'currentMinFrame') && ~isempty(posData.currentMinFrame)
        currentMin = posData.currentMinFrame;
    else
        currentMin = posData.minFrame;
    end
    if isfield(posData, 'currentMaxFrame') && ~isempty(posData.currentMaxFrame)
        currentMax = posData.currentMaxFrame;
    else
        currentMax = posData.maxFrame;
    end
    app.FramesSlider.Value = [currentMin, currentMax];
    app.FramesField.Value = [num2str(currentMin) ' ' num2str(currentMax)];
    
    % Mettre à jour la table des channels pour la première position par défaut
    updateChannelTable(app, 1);
end


function updateChannelTable(app, posIndex)
    % Met à jour la table des channels et le slider pour la position spécifiée.
    % Si posIndex n'est pas fourni, la première position est utilisée.
    
    if nargin < 2 || isempty(posIndex)
        posIndex = 1;
    end
    
    positions = app.parsedData.positions;
    posData = positions(posIndex);
    numChannels = posData.numChannels;
    
    % Préparer la table des channels avec 5 colonnes :
    % {Select, File Chan. Name, User Name, Frequency, Size}
    chanTableData = cell(numChannels, 5);
    for i = 1:numChannels
        chanIndex = i - 1;  % Numérotation à partir de 0
        chanTableData{i, 1} = posData.channelsSelected(i);            % Utilise le champ channelsSelected
        chanTableData{i, 2} = num2str(chanIndex);                       % File Chan. Name
        chanTableData{i, 3} = posData.userChanName{i};                  % User Name (champ userChanName)
        chanTableData{i, 4} = num2str(posData.channelFrequencies(i));   % Frequency
        chanTableData{i, 5} = posData.channelSizes{i};                  % Size
    end
    app.UIChannelTable.Data = chanTableData;
    
    % Mise à jour du slider des frames pour la position sélectionnée.
    if isfield(posData, 'minFrame')
        minFrame = posData.minFrame;
    else
        minFrame = min(posData.frames);
    end
    if isfield(posData, 'maxFrame')
        maxFrame = posData.maxFrame;
    else
        maxFrame = max(posData.frames);
    end
    
    if isfield(posData, 'currentMinFrame') && ~isempty(posData.currentMinFrame)
        currentMin = posData.currentMinFrame;
    else
        currentMin = minFrame;
    end
    if isfield(posData, 'currentMaxFrame') && ~isempty(posData.currentMaxFrame)
        currentMax = posData.currentMaxFrame;
    else
        currentMax = maxFrame;
    end
    
    currentMin=round(currentMin);
    currentMax=round(currentMax);

    app.FramesSlider.Limits = [minFrame, maxFrame];
    app.FramesSlider.Value = [currentMin, currentMax];
    app.FramesField.Value = [num2str(currentMin) ' ' num2str(currentMax)];
end


    end


    methods (Access = private)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          app.ProjectTab.Parent = [];
        app.ROIsTab.Parent = [];
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
   choice = questdlg('Voulez-vous vraiment annuler et fermer l''application ?', ...
                      'Annuler', 'Oui', 'Non', 'Non');
    if strcmp(choice, 'Oui')
        delete(app);  % Ferme l'application
    end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
    % Vérifier que des données ont été chargées
    if isempty(app.parsedData)
         uialert(app.LoadingdataUIFigure, 'Aucune donnée chargée.', 'Erreur');
         return;
    end

    loadData_load(app.parsedData);


        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)

       changingValue = event.Value;  % Vecteur 1x2 [min max]
    minSliderPos = changingValue(1);
    maxSliderPos = changingValue(2);
    
    % Mise à jour du champ de texte
    app.FramesField.Value = [num2str(round(minSliderPos)) ' ' num2str(round(maxSliderPos))];
    
    % Déterminer quelles positions mettre à jour
    if app.applychangestoallpositionsCheckBox.Value
        posIndices = 1:numel(app.parsedData.positions);
    else
        if ~isempty(app.UIPositionTable.Selection)
            posIndices = app.UIPositionTable.Selection(1);
        else
            posIndices = 1;
        end
    end
    
    % Mise à jour des champs currentMinFrame/currentMaxFrame pour chaque position concernée
    for posIndex = posIndices
        app.parsedData.positions(posIndex).currentMinFrame = minSliderPos;
        app.parsedData.positions(posIndex).currentMaxFrame = maxSliderPos;
    end
    
    % Actualiser l'affichage de la table des channels pour la position actuellement sélectionnée
    if ~isempty(app.UIPositionTable.Selection)
        currentPos = app.UIPositionTable.Selection(1);
    else
        currentPos = 1;
    end
    updateChannelTable(app, currentPos);

        end

        % Value changed function: FramesField
        function FramesFieldValueChanged(app, event)
        % Convertir la valeur du champ de texte en nombre
    value = str2num(app.FramesField.Value);  %#ok<ST2NM>
    lim = app.FramesSlider.Limits;
    newVal = [max(lim(1), value(1)) , min(lim(2), value(2))];
    
    % Mettre à jour le slider
    app.FramesSlider.Value = newVal;
    
    % Déterminer quelles positions mettre à jour
    if app.applychangestoallpositionsCheckBox.Value
        posIndices = 1:numel(app.parsedData.positions);
    else
        if ~isempty(app.UIPositionTable.Selection)
            posIndices = app.UIPositionTable.Selection(1);
        else
            posIndices = 1;
        end
    end
    
    % Mise à jour des champs currentMinFrame/currentMaxFrame pour chaque position concernée
    for posIndex = posIndices
        app.parsedData.positions(posIndex).currentMinFrame = newVal(1);
        app.parsedData.positions(posIndex).currentMaxFrame = newVal(2);
    end
    
    % Actualiser l'affichage de la table des channels pour la position actuellement sélectionnée
    if ~isempty(app.UIPositionTable.Selection)
        currentPos = app.UIPositionTable.Selection(1);
    else
        currentPos = 1;
    end
    updateChannelTable(app, currentPos);
    
        end

        % Value changed function: AdvancedmodeCheckBox
        function AdvancedmodeCheckBoxValueChanged(app, event)
            value = app.AdvancedmodeCheckBox.Value;
    if value
        % Advanced mode activé : réinsérer les onglets "Project" et "ROIs" dans le TabGroup,
        % si ils ne s'y trouvent pas déjà.
        if isempty(app.ProjectTab.Parent)
            app.ProjectTab.Parent = app.TabGroup;
        end
        if isempty(app.ROIsTab.Parent)
            app.ROIsTab.Parent = app.TabGroup;
        end
    else
        % Advanced mode désactivé : retirer les onglets du TabGroup
        app.ProjectTab.Parent = [];
        app.ROIsTab.Parent = [];
    end
        end

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            % Demander à l'utilisateur s'il souhaite sélectionner un dossier ou des fichiers
         
                    files={};
                    folder = uigetdir;
                    if folder ~= 0  % Vérifier que l'utilisateur n'a pas annulé
                        % Mettre à jour le champ de texte avec le chemin du dossier sélectionné
                        app.PathLabel.Text = folder;
                        app.parsedData.files={};
                    else
                        return;
                    end

             d = uiprogressdlg(app.LoadingdataUIFigure,'Title','Please Wait',...
           'Message','Parsing files in the selected directory...');
            d.Value=0.25;
             pause(0.2);
             d.Value=0.5;

             app.InvertframesandpositionsCheckBox.Value=false;
            app.parsedData = loadData_parse(files, folder,app.InvertframesandpositionsCheckBox.Value);
            app.parsedData.projectPath=fullfile(folder, "tmpProject.mat"); 
             app.InfoLabel.Text=[ num2str(numel(app.parsedData.positions)) ' Postions '];

            app.parsedData.files=files;
            app.parsedData.folder=folder;

            assignin('base','parsedData', app.parsedData);

            d.Value=1;
            pause(0.2);
            close(d);
            app.updatePositionTable;


        end

        % Selection changed function: UIPositionTable
        function UIPositionTableSelectionChanged(app, event)
          selection = app.UIPositionTable.Selection;
    % La sélection renvoie un tableau d'indices [row, column]
    if ~isempty(selection)
        posIndex = selection(1);  % Utiliser la première ligne sélectionnée comme indice
        updateChannelTable(app, posIndex);
    end


        end

        % Cell edit callback: UIPositionTable
        function UIPositionTableCellEdit(app, event)
            indices = event.Indices;  % [row, column]
    newData = event.NewData;
    row = indices(1);
    col = indices(2);
    
    % Mettre à jour la structure de la position correspondante selon la colonne éditée
    switch col
        case 1  % Colonne Select (checkbox)
            app.parsedData.positions(row).selected = newData;
        case 2  % Colonne File Pos Name
            app.parsedData.positions(row).filePosName = newData;
        case 3  % Colonne User Name
            app.parsedData.positions(row).userName = newData;
    end
            
        end

        % Cell edit callback: UIChannelTable
        function UIChannelTableCellEdit(app, event)
      indices = event.Indices;  % [row, column]
    newData = event.NewData;
    channelRow = indices(1);  % index du channel (ligne)
    col = indices(2);         % index de la colonne éditée

    % Vérifier si la checkbox "apply changes to all positions" est activée
    if app.applychangestoallpositionsCheckBox.Value
        posIndices = 1:numel(app.parsedData.positions);
    else
        if ~isempty(app.UIPositionTable.Selection)
            posIndices = app.UIPositionTable.Selection(1);
        else
            posIndices = 1;
        end
    end

    for posIndex = posIndices
        posData = app.parsedData.positions(posIndex);
        switch col
            case 1  % Colonne "Select"
                posData.channelsSelected(channelRow) = newData;
            case 3  % Colonne "User Name"
                % Initialiser userChanName s'il n'existe pas ou est trop court
                if ~isfield(posData, 'userChanName') || numel(posData.userChanName) < posData.numChannels
                    posData.userChanName = repmat({['Channel ' num2str(channelRow-1)]}, 1, posData.numChannels);
                end
                posData.userChanName{channelRow} = newData;
            % On ne traite pas les autres colonnes
        end
        % Mise à jour incrémentale dans la structure globale
        app.parsedData.positions(posIndex) = posData;
    end

    % Mettre à jour l'affichage de la table des channels pour la position actuellement sélectionnée
    if ~isempty(app.UIPositionTable.Selection)
        currentPos = app.UIPositionTable.Selection(1);
    else
        currentPos = 1;
    end
    updateChannelTable(app, currentPos);

    assignin('base','parsedData', app.parsedData);
            
        end

        % Value changed function: applychangestoallpositionsCheckBox
        function applychangestoallpositionsCheckBoxValueChanged(app, event)
            value = app.applychangestoallpositionsCheckBox.Value;
            
        end

        % Button pushed function: SetProjectButton
        function SetProjectButtonPushed(app, event)
            
        end

        % Value changed function: InvertframesandpositionsCheckBox
        function InvertframesandpositionsCheckBoxValueChanged(app, event)
            value = app.InvertframesandpositionsCheckBox.Value;
            
           
            folder=app.PathLabel.Text;

            if ~strcmp(folder,'Path')
             
                   d = uiprogressdlg(app.LoadingdataUIFigure,'Title','Please Wait',...
           'Message','Parsing files in the selected directory...');
            d.Value=0.25;
             pause(0.2);
             d.Value=0.5;

             files=app.parsedData.files ;
             folder=app.parsedData.folder;
            app.parsedData = loadData_parse(files, folder,app.InvertframesandpositionsCheckBox.Value);
            app.parsedData.files=files;
            app.parsedData.folder=folder;

            if ~isfield(app.parsedData,'projectPath')
            app.parsedData.projectPath=fullfile(folder, "tmpProject.mat"); 
            app.OutputprojectfileEditField.Value=app.parsedData.projectPath; 
            end

              d.Value=1;
            pause(0.2);
            close(d);

            app.updatePositionTable;
             assignin('base','parsedData', app.parsedData);
            end

        end

        % Button pushed function: ChoosefilesButton
        function ChoosefilesButtonPushed(app, event)
                       % Demander à l'utilisateur s'il souhaite sélectionner un dossier ou des fichiers
         
                    [files, folder] = uigetfile({'*.*','All fils'}, ...
                        'Select files', 'MultiSelect', 'on');
                    if isequal(files, 0)
                        % L'utilisateur a annulé la sélection
                        return;
                    end
    
             d = uiprogressdlg(app.LoadingdataUIFigure,'Title','Please Wait',...
           'Message','Parsing files in the selected directory...');
            d.Value=0.25;
             pause(0.2);
             d.Value=0.5;

            app.InvertframesandpositionsCheckBox.Value=false;
            app.parsedData = loadData_parse(files, folder,app.InvertframesandpositionsCheckBox.Value);
            app.parsedData.projectPath=fullfile(folder, "tmpProject.mat"); 
            app.parsedData.files=files;
            app.parsedData.folder=folder;
            
            app.PathLabel.Text=folder;
            app.InfoLabel.Text=[ num2str(numel(app.parsedData.positions)) 'Postions '];
            assignin('base','parsedData', app.parsedData);

            app.updatePositionTable;


            d.Value=1;
            pause(0.2);
            close(d);

        end

        % Value changed function: FramesSlider_2
        function FramesSlider_2ValueChanged(app, event)
            value = app.FramesSlider_2.Value;
            
        end

        % Value changed function: ChannelSlider
        function ChannelSliderValueChanged(app, event)
            value = app.ChannelSlider.Value;
            
        end

        % Value changed function: PositionSlider
        function PositionSliderValueChanged(app, event)
            value = app.PositionSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadingdataUIFigure = uifigure('Visible', 'off');
            app.LoadingdataUIFigure.Name = 'Loading data....';
            app.CancelButton = uibutton(app.LoadingdataUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TabGroup = uitabgroup(app.LoadingdataUIFigure);
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.ChoosefolderButton = uibutton(app.ImagesTab, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder....';
            app.AdvancedmodeCheckBox = uicheckbox(app.ImagesTab);
            app.AdvancedmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedmodeCheckBoxValueChanged, true);
            app.AdvancedmodeCheckBox.Text = 'Advanced mode';
            app.InvertframesandpositionsCheckBox = uicheckbox(app.ImagesTab);
            app.InvertframesandpositionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertframesandpositionsCheckBoxValueChanged, true);
            app.InvertframesandpositionsCheckBox.Text = 'Invert frames and positions';
            app.ChannelsPanel = uipanel(app.ImagesTab);
            app.ChannelsPanel.Title = 'Channels';
            app.UIChannelTable = uitable(app.ChannelsPanel);
            app.UIChannelTable.ColumnName = {'Select'; 'File Chan. Name'; 'User Name'; 'Frequency'; 'Size'};
            app.UIChannelTable.RowName = {};
            app.UIChannelTable.ColumnEditable = [true false true];
            app.UIChannelTable.CellEditCallback = createCallbackFcn(app, @UIChannelTableCellEdit, true);
            app.FramesSliderLabel = uilabel(app.ChannelsPanel);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.ChannelsPanel, 'range');
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesField = uieditfield(app.ChannelsPanel, 'text');
            app.FramesField.ValueChangedFcn = createCallbackFcn(app, @FramesFieldValueChanged, true);
            app.applychangestoallpositionsCheckBox = uicheckbox(app.ChannelsPanel);
            app.applychangestoallpositionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @applychangestoallpositionsCheckBoxValueChanged, true);
            app.applychangestoallpositionsCheckBox.Text = 'apply changes to all positions';
            app.applychangestoallpositionsCheckBox.Value = true;
            app.PositionsPanel = uipanel(app.ImagesTab);
            app.PositionsPanel.Title = 'Positions';
            app.UIPositionTable = uitable(app.PositionsPanel);
            app.UIPositionTable.ColumnName = {'Select'; 'File Pos Name'; 'User Name'};
            app.UIPositionTable.RowName = {};
            app.UIPositionTable.ColumnEditable = [true false true];
            app.UIPositionTable.CellEditCallback = createCallbackFcn(app, @UIPositionTableCellEdit, true);
            app.UIPositionTable.SelectionChangedFcn = createCallbackFcn(app, @UIPositionTableSelectionChanged, true);
            app.ORLabel = uilabel(app.ImagesTab);
            app.ORLabel.Text = 'OR';
            app.ChoosefilesButton = uibutton(app.ImagesTab, 'push');
            app.ChoosefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefilesButtonPushed, true);
            app.ChoosefilesButton.Text = 'Choose files...';
            app.PathLabel = uilabel(app.ImagesTab);
            app.PathLabel.Text = 'Path';
            app.InfoLabel = uilabel(app.ImagesTab);
            app.InfoLabel.Text = 'Info';
            app.ProjectTab = uitab(app.TabGroup);
            app.ProjectTab.Title = 'Project';
            app.OutputprojectfileEditFieldLabel = uilabel(app.ProjectTab);
            app.OutputprojectfileEditFieldLabel.Text = 'Output project file:';
            app.OutputprojectfileEditField = uieditfield(app.ProjectTab, 'text');
            app.SetProjectButton = uibutton(app.ProjectTab, 'push');
            app.SetProjectButton.ButtonPushedFcn = createCallbackFcn(app, @SetProjectButtonPushed, true);
            app.SetProjectButton.Text = 'Set...';
            app.ROIsTab = uitab(app.TabGroup);
            app.ROIsTab.Title = 'ROIs';
            app.UIAxes = uiaxes(app.ROIsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ROIsButtonGroup = uibuttongroup(app.ROIsTab);
            app.ROIsButtonGroup.Title = 'ROIs';
            app.FullframeROIsButton = uiradiobutton(app.ROIsButtonGroup);
            app.FullframeROIsButton.Text = 'Full frame ROIs';
            app.FullframeROIsButton.Value = true;
            app.DivideframeintoButton = uiradiobutton(app.ROIsButtonGroup);
            app.DivideframeintoButton.Text = 'Divide frame into ';
            app.CustomROIsButton = uiradiobutton(app.ROIsButtonGroup);
            app.CustomROIsButton.Text = 'Custom ROIs';
            app.ROIDivide = uieditfield(app.ROIsButtonGroup, 'numeric');
            app.ROIDivide.Value = 4;
            app.CreatROIButton = uibutton(app.ROIsButtonGroup, 'push');
            app.CreatROIButton.Text = 'Creat ROI';
            app.FindallButton = uibutton(app.ROIsButtonGroup, 'push');
            app.FindallButton.Text = 'Find all';
            app.DimensionsEditFieldLabel = uilabel(app.ROIsButtonGroup);
            app.DimensionsEditFieldLabel.Text = 'Dimensions:';
            app.DimensionsEditField = uieditfield(app.ROIsButtonGroup, 'text');
            app.correctdriftCheckBox = uicheckbox(app.ROIsButtonGroup);
            app.correctdriftCheckBox.Text = 'correct drift';
            app.FramesSlider_2Label = uilabel(app.ROIsTab);
            app.FramesSlider_2Label.Text = 'Frames';
            app.FramesSlider_2 = uislider(app.ROIsTab);
            app.FramesSlider_2.ValueChangedFcn = createCallbackFcn(app, @FramesSlider_2ValueChanged, true);
            app.ChannelSliderLabel = uilabel(app.ROIsTab);
            app.ChannelSliderLabel.Text = 'Channel';
            app.ChannelSlider = uislider(app.ROIsTab);
            app.ChannelSlider.ValueChangedFcn = createCallbackFcn(app, @ChannelSliderValueChanged, true);
            app.PositionSliderLabel = uilabel(app.ROIsTab);
            app.PositionSliderLabel.Text = 'Position';
            app.PositionSlider = uislider(app.ROIsTab);
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.LoadButton = uibutton(app.LoadingdataUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.LoadingdataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadingdataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadingdataUIFigure)
        end
    end
end