classdef pipelineGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConnectDisconnectmodulesButton  matlab.ui.control.Button
        UIModuleParametersTable         matlab.ui.control.Table
        OpenselectedmoduleButton        matlab.ui.control.Button
        CreaterunButton                 matlab.ui.control.Button
        CheckpipelineButton             matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        RunpipelineButton               matlab.ui.control.Button
        UIModuleListTable               matlab.ui.control.Table
        AddmoduleButton                 matlab.ui.control.Button
        ModuletypeDropDown              matlab.ui.control.DropDown
        ModuletypeDropDownLabel         matlab.ui.control.Label
        UIModulesAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Data struct = struct('nodes',[],'edges',[])
        ModuleHandles = gobjects(0)
        ModuleTextHandles = gobjects(0)
        ModuleMarkers = gobjects(0)
        EdgeHandles = gobjects(0)
        SelectedModules double = []
        PendingAddModule logical = false
        PendingTemplate struct = struct()
        DraggingModule double = NaN
        DragOffset double = [0 0]
        ModuleIdCounter double = 0
        Context struct = struct()
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj, pipeObj)
            if nargin >= 2 && ~isempty(shallowObj)
                app.Context.shallow = shallowObj;
                app.Context.shallowObj = shallowObj;
            end
            if nargin >= 3 && ~isempty(pipeObj)
                if isa(pipeObj, 'pipeline')
                    app.Context.pipeObj = pipeObj;
                end
                [nodes, edges] = unpackPipeline(app, pipeObj);
                app.Data.nodes = nodes;
                app.Data.edges = edges;
            end

            app.ModuleIdCounter = numel(app.Data.nodes);

            initAxes(app);
            initTables(app);
            redrawAll(app);
            createDynamicSaveButton(app);
        end

        % Button pushed function: AddmoduleButton
        function AddmoduleButtonPushed(app, event)
            app.PendingAddModule = true;
            app.PendingTemplate = getTemplateByDisplay(app, app.ModuletypeDropDown.Value);
        end

        % Button pushed function: ConnectDisconnectmodulesButton
        function ConnectDisconnectmodulesButtonPushed(app, event)
            if numel(app.SelectedModules) ~= 2
                uialert(app.UIFigure, 'Select 2 modules (shift+click) before connecting.', 'Info');
                return;
            end

            a = app.SelectedModules(1);
            b = app.SelectedModules(2);
            if a == b
                return;
            end

            if edgeExists(app, a, b)
                removeEdge(app, a, b);
            else
                addEdge(app, a, b);
            end

            redrawEdges(app);
            refreshStatus(app);
        end

        % Button down function: UIModulesAxes
        function UIModulesAxesButtonDown(app, event)
            if app.PendingAddModule
                pt = app.UIModulesAxes.CurrentPoint;
                pos = [pt(1,1) pt(1,2)];
                addModuleAt(app, pos);
                app.PendingAddModule = false;
                return;
            end

            clearSelection(app);
        end

        % Selection changed function: UIModuleListTable
        function UIModuleListTableSelectionChanged(app, event)
            sel = app.UIModuleListTable.Selection;
            if isempty(sel)
                return;
            end
            row = sel(1,1);
            if row > numel(app.Data.nodes)
                return;
            end

            setSelection(app, row, false);
            updateParamsTable(app, row);
        end

        % Cell edit callback: UIModuleListTable
        function UIModuleListTableCellEdit(app, event)
            idx = event.Indices;
            if isempty(idx)
                return;
            end
            row = idx(1);
            col = idx(2);

            if row > numel(app.Data.nodes)
                return;
            end

            node = app.Data.nodes(row);
            val = event.NewData;

            switch col
                case 1
                    node.enabled = logical(val);
                case 2
                    node.name = char(string(val));
                case 3
                    pkgVal = char(string(val));
                    if strcmp(pkgVal, '<none>')
                        pkgVal = '';
                    end
                    node.pkg = pkgVal;
                    applyPackageToNode(app, row, node.pkg);
                    node = app.Data.nodes(row);
            end

            app.Data.nodes(row) = node;
            redrawModule(app, row);
            updateModuleListTable(app);
            refreshStatus(app);
        end

        % Cell edit callback: UIModuleParametersTable
        function UIModuleParametersTableCellEdit(app, event)
            idx = event.Indices;
            if isempty(idx)
                return;
            end
            row = idx(1);
            col = idx(2);
            if col ~= 2
                return;
            end
            if isempty(app.SelectedModules)
                return;
            end

            modIdx = app.SelectedModules(1);
            if modIdx > numel(app.Data.nodes)
                return;
            end

            data = app.UIModuleParametersTable.Data;
            if isempty(data) || row > size(data,1)
                return;
            end

            key = char(string(data{row,1}));
            val = data{row,2};

            node = app.Data.nodes(modIdx);
            if ~isfield(node,'params') || isempty(node.params)
                node.params = struct();
            end
            node.params.(key) = val;
            app.Data.nodes(modIdx) = node;

            refreshStatus(app);
        end

        % Selection changed function: UIModuleParametersTable
        function UIModuleParametersTableSelectionChanged(app, event)
            selection = app.UIModuleParametersTable.Selection;
            
        end

        % Button pushed function: RunpipelineButton
        function RunpipelineButtonPushed(app, event)
            pipe = buildPipelineStruct(app, false);
            ctx = app.Context;
            if ~isfield(ctx,'allowGUI')
                ctx.allowGUI = true;
            end
            try
                runPipeline(pipe, ctx);
            catch ME
                uialert(app.UIFigure, ME.message, 'Run failed', 'Icon','error');
            end
            refreshStatus(app);
        end

        % Button pushed function: SavepipelineButton
        function SavepipelineButtonPushed(app, event)
            try
                savePipelineFromGUI(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Save failed', 'Icon','error');
            end
        end

        % Button pushed function: CheckpipelineButton
        function CheckpipelineButtonPushed(app, event)
            refreshStatus(app, true);
        end

        % Button pushed function: OpenselectedmoduleButton
        function OpenselectedmoduleButtonPushed(app, event)
            if isempty(app.SelectedModules)
                return;
            end
            openModule(app, app.SelectedModules(1));
        end

        % Button pushed function: CreaterunButton
        function CreaterunButtonPushed(app, event)
            pipe = buildPipelineStruct(app, true);
            shallowObj = [];
            if isfield(app.Context,'shallow') && ~isempty(app.Context.shallow)
                shallowObj = app.Context.shallow;
            elseif isfield(app.Context,'shallowObj') && ~isempty(app.Context.shallowObj)
                shallowObj = app.Context.shallowObj;
            end

            try
                if isempty(shallowObj)
                    feval('pipelineRunGUI', pipe);
                else
                    feval('pipelineRunGUI', pipe, shallowObj);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Run GUI error', 'Icon','error');
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Window motion for dragging
        function UIFigureWindowButtonMotion(app, event)
            if isnan(app.DraggingModule)
                return;
            end
            idx = app.DraggingModule;
            if idx > numel(app.Data.nodes)
                return;
            end

            pt = app.UIModulesAxes.CurrentPoint;
            x = pt(1,1) - app.DragOffset(1);
            y = pt(1,2) - app.DragOffset(2);

            node = app.Data.nodes(idx);
            node.layout(1) = x;
            node.layout(2) = y;
            app.Data.nodes(idx) = node;

            redrawModule(app, idx);
            redrawEdges(app);
        end

        % Window up to stop dragging
        function UIFigureWindowButtonUp(app, event)
            app.DraggingModule = NaN;
        end
    end

    % Internal helpers
    methods (Access = private)

        function initAxes(app)
            cla(app.UIModulesAxes);
            app.UIModulesAxes.XLim = [0 100];
            app.UIModulesAxes.YLim = [0 100];
            app.UIModulesAxes.YDir = 'reverse';
            axis(app.UIModulesAxes, 'manual');
            hold(app.UIModulesAxes, 'on');
        end

        function initTables(app)
            app.UIModuleListTable.ColumnEditable = [true true true false false false];
            app.UIModuleListTable.ColumnFormat = { ...
                'logical', ...
                'char', ...
                getModulePackageList(app), ...
                'char', ...
                'char', ...
                'char' ...
            };

            app.UIModuleParametersTable.ColumnEditable = [false true];
            updateModuleListTable(app);
        end

        function list = getModuleTypeDisplay(app)
            reg = getModuleRegistry(app);
            list = {reg.display};
        end

        function list = getModulePackageList(app)
            proc = getProcessorPackageList(app);
            clas = getClassifierPackageList(app);
            list = unique([proc(:)', clas(:)'], 'stable');
            list = list(~cellfun(@isempty, list));
            if isempty(list)
                list = {'<none>'};
            else
                list = [{'<none>'}, list];
            end
        end

        function names = getProcessorPackageList(app)
            names = {};
            try
                procList = listProcessPackages();
                if istable(procList)
                    names = procList{:,2};
                else
                    names = procList(:,2);
                end
                names = cellstr(names);
            catch
                names = {};
            end
        end

        function names = getClassifierPackageList(app)
            names = {};
            try
                classFile = which('classi.m');
                if isempty(classFile)
                    return;
                end
                classDir = fileparts(fileparts(classFile));
                clFile = fullfile(classDir, 'classification', 'classlist.mat');
                if ~exist(clFile,'file')
                    return;
                end
                S = load(clFile, 'classlist');
                classlist = S.classlist;
                if istable(classlist)
                    names = classlist.Name;
                else
                    names = classlist(:,2);
                end
                names = cellstr(names);
            catch
                names = {};
            end
        end

        function row = getClasslistRowByName(app, name)
            row = [];
            try
                classFile = which('classi.m');
                if isempty(classFile)
                    return;
                end
                classDir = fileparts(fileparts(classFile));
                clFile = fullfile(classDir, 'classification', 'classlist.mat');
                if ~exist(clFile,'file')
                    return;
                end
                S = load(clFile, 'classlist');
                classlist = S.classlist;
                if istable(classlist)
                    idx = find(strcmp(classlist.Name, name), 1);
                    if isempty(idx)
                        return;
                    end
                    row = classlist(idx,:);
                else
                    idx = find(strcmp(classlist(:,2), name), 1);
                    if isempty(idx)
                        return;
                    end
                    row = classlist(idx,:);
                end
            catch
                row = [];
            end
        end

        function fun = getClassifyFunFromRow(app, row)
            fun = '';
            try
                if istable(row)
                    if width(row) >= 6
                        fun = row{1,6};
                    end
                else
                    fun = row{1,6};
                end
                if iscell(fun)
                    fun = fun{1};
                end
                if isstring(fun)
                    fun = char(fun);
                end
            catch
                fun = '';
            end
        end

        function reg = getModuleRegistry(app)
            reg = struct('display',{},'type',{},'func',{},'gui',{}, ...
                'paramRequired',{},'inputs',{},'outputs',{},'defaultParams',{},'color',{});

            reg(1) = struct( ...
                'display','Dataloader', ...
                'type','dataLoader', ...
                'func','dataLoader.process', ...
                'gui','dataLoader.ui', ...
                'paramRequired',{{'path'}}, ...
                'inputs',{{}}, ...
                'outputs',{{'fovList','channels'}}, ...
                'defaultParams',safeSetParam(app, 'dataLoader.setparam'), ...
                'color',[0.18 0.52 0.94]);

            reg(2) = struct( ...
                'display','ROI identification', ...
                'type','roiIdentify', ...
                'func','roiIdentify.process', ...
                'gui','roiIdentify.ui', ...
                'paramRequired',{{}}, ...
                'inputs',{{'fovList'}}, ...
                'outputs',{{'roiList','channels'}}, ...
                'defaultParams',safeSetParam(app, 'roiIdentify.setparam'), ...
                'color',[0.98 0.60 0.20]);

            reg(3) = struct( ...
                'display','ROI extraction', ...
                'type','roiExtract', ...
                'func','roiExtract.process', ...
                'gui','', ...
                'paramRequired',{{}}, ...
                'inputs',{{'roiList'}}, ...
                'outputs',{{'roiList','dataSeries'}}, ...
                'defaultParams',safeSetParam(app, 'roiExtract.setparam'), ...
                'color',[0.10 0.68 0.38]);

            reg(4) = struct( ...
                'display','Processor', ...
                'type','processor', ...
                'func','', ...
                'gui','processDataGUI', ...
                'paramRequired',{{'pkg'}}, ...
                'inputs',{{'roiList'}}, ...
                'outputs',{{'dataSeries'}}, ...
                'defaultParams',struct('pkg',''), ...
                'color',[0.55 0.55 0.55]);

            reg(5) = struct( ...
                'display','Classifier', ...
                'type','classifier', ...
                'func','', ...
                'gui','classifierGUI', ...
                'paramRequired',{{'pkg'}}, ...
                'inputs',{{'roiList'}}, ...
                'outputs',{{'dataSeries'}}, ...
                'defaultParams',struct('pkg',''), ...
                'color',[0.40 0.40 0.70]);
        end

        function params = safeSetParam(app, funName)
            try
                params = feval(funName, struct());
            catch
                params = struct();
            end
        end

        function tpl = getTemplateByDisplay(app, displayName)
            reg = getModuleRegistry(app);
            idx = find(strcmpi({reg.display}, displayName), 1);
            if isempty(idx)
                tpl = reg(1);
            else
                tpl = reg(idx);
            end
        end

        function displayName = getDisplayFromType(app, typeName)
            reg = getModuleRegistry(app);
            idx = find(strcmpi({reg.type}, typeName), 1);
            if isempty(idx)
                displayName = typeName;
            else
                displayName = reg(idx).display;
            end
        end

        function addModuleAt(app, pos)
            tpl = app.PendingTemplate;
            if isempty(fieldnames(tpl))
                tpl = getTemplateByDisplay(app, app.ModuletypeDropDown.Value);
            end

            app.ModuleIdCounter = app.ModuleIdCounter + 1;
            id = sprintf('%s_%d', lower(tpl.type), app.ModuleIdCounter);
            if ~isempty(app.Data.nodes)
                existing = {app.Data.nodes.id};
                while any(strcmp(existing, id))
                    app.ModuleIdCounter = app.ModuleIdCounter + 1;
                    id = sprintf('%s_%d', lower(tpl.type), app.ModuleIdCounter);
                end
            end

            node = struct();
            node.id = id;
            node.name = id;
            node.type = tpl.type;
            node.func = tpl.func;
            node.gui = tpl.gui;
            node.guiMode = 'replace';
            node.paramRequired = tpl.paramRequired;
            node.inputs = tpl.inputs;
            node.outputs = tpl.outputs;
            node.params = tpl.defaultParams;
            node.enabled = true;
            node.status = '';
            node.pkg = '';
            node.layout = [pos(1) pos(2) 20 10];

            if isempty(app.Data.nodes)
                app.Data.nodes = node;
            else
                app.Data.nodes(end+1) = node;
            end

            drawModule(app, numel(app.Data.nodes));
            updateModuleListTable(app);
            refreshStatus(app);
        end

        function drawModule(app, idx)
            node = app.Data.nodes(idx);
            [x,y,w,h] = deal(node.layout(1), node.layout(2), node.layout(3), node.layout(4));
            pts = [x y; x+w-4 y; x+w y+h/2; x+w-4 y+h; x y+h];

            col = getModuleColor(app, node);

            hPatch = patch(app.UIModulesAxes, pts(:,1), pts(:,2), col, ...
                'EdgeColor','k','LineWidth',0.5,'ButtonDownFcn',@app.modulePatchButtonDown);
            hPatch.UserData = idx;

            hText = text(app.UIModulesAxes, x+1, y+h/2, node.name, ...
                'VerticalAlignment','middle', 'FontSize',8, 'Interpreter','none');

            hMarker = plot(app.UIModulesAxes, x+w-2, y+2, 'o', 'MarkerSize',6, ...
                'MarkerEdgeColor','k', 'MarkerFaceColor',[0 0.8 0], 'Visible','off');

            if idx > numel(app.ModuleHandles)
                app.ModuleHandles(idx) = hPatch;
                app.ModuleTextHandles(idx) = hText;
                app.ModuleMarkers(idx) = hMarker;
            else
                app.ModuleHandles(idx) = hPatch;
                app.ModuleTextHandles(idx) = hText;
                app.ModuleMarkers(idx) = hMarker;
            end

            cm = uicontextmenu(app.UIFigure);
            uimenu(cm,'Text','Open module','MenuSelectedFcn',@(s,e)openModule(app, idx));
            uimenu(cm,'Text','Delete module','MenuSelectedFcn',@(s,e)deleteModule(app, idx));
            hPatch.ContextMenu = cm;

            redrawModule(app, idx);
            redrawEdges(app);
        end

        function redrawModule(app, idx)
            if idx > numel(app.Data.nodes) || idx > numel(app.ModuleHandles)
                return;
            end
            node = app.Data.nodes(idx);
            hPatch = app.ModuleHandles(idx);
            hText = app.ModuleTextHandles(idx);
            hMarker = app.ModuleMarkers(idx);
            if isempty(hPatch) || ~isvalid(hPatch)
                return;
            end

            [x,y,w,h] = deal(node.layout(1), node.layout(2), node.layout(3), node.layout(4));
            pts = [x y; x+w-4 y; x+w y+h/2; x+w-4 y+h; x y+h];
            hPatch.XData = pts(:,1);
            hPatch.YData = pts(:,2);
            hPatch.FaceColor = getModuleColor(app, node);

            hText.Position = [x+1 y+h/2 0];
            hText.String = node.name;

            if node.enabled
                hMarker.Visible = 'on';
                hMarker.XData = x+w-2;
                hMarker.YData = y+2;
            else
                hMarker.Visible = 'off';
            end

            updateSelectionStyle(app);
        end

        function redrawAll(app)
            cla(app.UIModulesAxes);
            hold(app.UIModulesAxes,'on');

            app.ModuleHandles = gobjects(0);
            app.ModuleTextHandles = gobjects(0);
            app.ModuleMarkers = gobjects(0);
            app.EdgeHandles = gobjects(0);

            for i = 1:numel(app.Data.nodes)
                drawModule(app, i);
            end
            redrawEdges(app);
            updateModuleListTable(app);
            refreshStatus(app);
        end

        function redrawEdges(app)
            if ~isempty(app.EdgeHandles)
                try
                    delete(app.EdgeHandles(ishandle(app.EdgeHandles)));
                catch
                end
            end
            app.EdgeHandles = gobjects(0);

            for i = 1:numel(app.Data.edges)
                e = app.Data.edges(i);
                if e.from > numel(app.Data.nodes) || e.to > numel(app.Data.nodes)
                    continue;
                end
                n1 = app.Data.nodes(e.from);
                n2 = app.Data.nodes(e.to);
                x1 = n1.layout(1) + n1.layout(3);
                y1 = n1.layout(2) + n1.layout(4)/2;
                x2 = n2.layout(1);
                y2 = n2.layout(2) + n2.layout(4)/2;
                h = plot(app.UIModulesAxes, [x1 x2], [y1 y2], '-', 'Color',[0 0 0]);
                app.EdgeHandles(end+1) = h; %#ok<AGROW>
            end
        end

        function setSelection(app, idx, additive)
            if nargin < 3
                additive = false;
            end
            if ~additive
                app.SelectedModules = idx;
            else
                app.SelectedModules = unique([app.SelectedModules idx]);
                if numel(app.SelectedModules) > 2
                    app.SelectedModules = app.SelectedModules(end-1:end);
                end
            end
            updateSelectionStyle(app);

            if idx <= size(app.UIModuleListTable.Data,1)
                app.UIModuleListTable.Selection = [idx 2];
            end
        end

        function clearSelection(app)
            app.SelectedModules = [];
            updateSelectionStyle(app);
        end

        function updateSelectionStyle(app)
            for i = 1:numel(app.ModuleHandles)
                if isempty(app.ModuleHandles(i)) || ~isvalid(app.ModuleHandles(i))
                    continue;
                end
                if ismember(i, app.SelectedModules)
                    app.ModuleHandles(i).LineWidth = 2;
                else
                    app.ModuleHandles(i).LineWidth = 0.5;
                end
            end
        end

        function updateModuleListTable(app)
            n = numel(app.Data.nodes);
            data = cell(n,6);
            for i = 1:n
                node = app.Data.nodes(i);
                data{i,1} = logical(getfielddefault(app, node,'enabled',true));
                data{i,2} = getfielddefault(app, node,'name',node.id);
                data{i,3} = getfielddefault(app, node,'pkg','');
                data{i,4} = strjoin(cellstr(node.inputs(:)), ', ');
                data{i,5} = strjoin(cellstr(node.outputs(:)), ', ');
                data{i,6} = getfielddefault(app, node,'status','');
            end
            app.UIModuleListTable.Data = data;
        end

        function updateParamsTable(app, idx)
            if idx > numel(app.Data.nodes)
                app.UIModuleParametersTable.Data = {};
                return;
            end
            node = app.Data.nodes(idx);
            if ~isfield(node,'params') || isempty(node.params)
                app.UIModuleParametersTable.Data = {};
                return;
            end
            fn = fieldnames(node.params);
            data = cell(numel(fn),2);
            for i = 1:numel(fn)
                data{i,1} = fn{i};
                data{i,2} = node.params.(fn{i});
            end
            app.UIModuleParametersTable.Data = data;
        end

        function refreshStatus(app, showAlert)
            if nargin < 2
                showAlert = false;
            end

            pipe = buildPipelineStruct(app, true);
            ctx = app.Context;
            try
                [ok, report] = runPipelineDry(pipe, ctx, struct('allowGui', false));
            catch
                ok = false;
                report = struct('errors',{{'Validation error'}},'missingParams',[]);
            end

            missingMap = containers.Map();
            if isfield(report,'missingParams') && ~isempty(report.missingParams)
                for i = 1:numel(report.missingParams)
                    entry = report.missingParams{i};
                    missingMap(entry.node) = entry.missing;
                end
            end

            nodes = app.Data.nodes;
            if isempty(nodes)
                return;
            end
            if ~isfield(nodes,'status')
                [nodes.status] = deal('');
            end
            if ~isfield(nodes,'enabled')
                [nodes.enabled] = deal(true);
            end
            if ~isfield(nodes,'pkg')
                [nodes.pkg] = deal('');
            end

            for i = 1:numel(nodes)
                node = nodes(i);
                if ~getfielddefault(app, node,'enabled',true)
                    node.status = 'Disabled';
                elseif isKey(missingMap, node.id)
                    miss = missingMap(node.id);
                    node.status = ['Missing: ' strjoin(miss, ', ')];
                else
                    node.status = 'OK';
                end
                nodes(i) = node;
            end

            app.Data.nodes = nodes;
            updateModuleListTable(app);

            if showAlert && ~ok
                msg = strjoin(report.errors, newline);
                uialert(app.UIFigure, msg, 'Pipeline issues', 'Icon','warning');
            end
        end

        function tf = edgeExists(app, a, b)
            tf = false;
            for i = 1:numel(app.Data.edges)
                if app.Data.edges(i).from == a && app.Data.edges(i).to == b
                    tf = true;
                    return;
                end
            end
        end

        function addEdge(app, a, b)
            e = struct('from',a,'to',b,'condition','');
            if isempty(app.Data.edges)
                app.Data.edges = e;
            else
                app.Data.edges(end+1) = e;
            end
        end

        function removeEdge(app, a, b)
            if isempty(app.Data.edges)
                return;
            end
            keep = true(1,numel(app.Data.edges));
            for i = 1:numel(app.Data.edges)
                if app.Data.edges(i).from == a && app.Data.edges(i).to == b
                    keep(i) = false;
                end
            end
            app.Data.edges = app.Data.edges(keep);
        end

        function deleteModule(app, idx)
            if idx > numel(app.Data.nodes)
                return;
            end

            keep = true(1,numel(app.Data.edges));
            for i = 1:numel(app.Data.edges)
                if app.Data.edges(i).from == idx || app.Data.edges(i).to == idx
                    keep(i) = false;
                end
            end
            app.Data.edges = app.Data.edges(keep);

            app.Data.nodes(idx) = [];

            if idx <= numel(app.ModuleHandles)
                delete(app.ModuleHandles(idx));
                delete(app.ModuleTextHandles(idx));
                delete(app.ModuleMarkers(idx));
                app.ModuleHandles(idx) = [];
                app.ModuleTextHandles(idx) = [];
                app.ModuleMarkers(idx) = [];
            end

            for i = 1:numel(app.ModuleHandles)
                if isvalid(app.ModuleHandles(i))
                    app.ModuleHandles(i).UserData = i;
                end
            end

            redrawEdges(app);
            updateModuleListTable(app);
            refreshStatus(app);
        end

        function openModule(app, idx)
            if idx > numel(app.Data.nodes)
                return;
            end
            node = app.Data.nodes(idx);
            ctx = app.Context;
            shallowObj = [];
            if isfield(ctx,'shallow') && ~isempty(ctx.shallow)
                shallowObj = ctx.shallow;
            elseif isfield(ctx,'shallowObj') && ~isempty(ctx.shallowObj)
                shallowObj = ctx.shallowObj;
            end

            try
                if strcmpi(node.type,'processor')
                    if ~isempty(shallowObj)
                        % Same syntax as detecdiv: processDataGUI(shallowObj, proc)
                        tmpProc = process(tempdir, 'pipeline_module', 1);
                        if ~isempty(getfielddefault(app,node,'pkg',''))
                            tmpProc.processFun = [node.pkg '.process'];
                        elseif ~isempty(getfielddefault(app,node,'func',''))
                            tmpProc.processFun = node.func;
                        end
                        if isfield(node,'params') && isstruct(node.params)
                            tmpProc.processArg = node.params;
                        end
                        processDataGUI(shallowObj, tmpProc);
                    else
                        processDataGUI;
                    end
                    return;
                end

                if strcmpi(node.type,'classifier')
                    % Same syntax as detecdiv: classifierGUI(classiObj)
                    tmpClassi = classi(tempdir, 'pipeline_module', 1);
                    if ~isempty(getfielddefault(app,node,'pkg',''))
                        tmpClassi.classifierPkg = node.pkg;
                    end
                    if ~isempty(getfielddefault(app,node,'func',''))
                        tmpClassi.classifyFun = node.func;
                    end
                    classifierGUI(tmpClassi);
                    return;
                end

                if ~isfield(node,'gui') || isempty(node.gui)
                    uialert(app.UIFigure, 'No GUI associated with this module.', 'Info');
                    return;
                end

                guiFn = node.gui;
                if isstring(guiFn)
                    guiFn = char(guiFn);
                end
                if ischar(guiFn)
                    if exist(guiFn,'file') == 0 && exist(guiFn,'class') == 0
                        uialert(app.UIFigure, ['GUI not available: ' guiFn], 'Info');
                        return;
                    end
                end

                if ~isempty(shallowObj)
                    feval(guiFn, shallowObj);
                else
                    feval(guiFn);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'GUI error', 'Icon','warning');
            end

            refreshStatus(app);
        end

        function applyTypeToNode(app, idx, displayName)
            tpl = getTemplateByDisplay(app, displayName);
            node = app.Data.nodes(idx);
            node.type = tpl.type;
            node.func = tpl.func;
            node.gui = tpl.gui;
            node.paramRequired = tpl.paramRequired;
            node.inputs = tpl.inputs;
            node.outputs = tpl.outputs;
            node.params = tpl.defaultParams;
            node.pkg = '';

            if strcmpi(node.type,'processor') || strcmpi(node.type,'classifier')
                node.paramRequired = {'pkg'};
                if strcmpi(node.type,'processor')
                    node.gui = 'processDataGUI';
                else
                    node.gui = 'classifierGUI';
                end
            end

            app.Data.nodes(idx) = node;
        end

        function applyPackageToNode(app, idx, pkgName)
            if idx > numel(app.Data.nodes)
                return;
            end
            node = app.Data.nodes(idx);
            if nargin < 3
                pkgName = '';
            end
            pkgName = char(string(pkgName));

            node.pkg = pkgName;
            if ~isfield(node,'params') || isempty(node.params)
                node.params = struct();
            end
            node.params.pkg = pkgName;

            % Infer specialized node kind from package when possible.
            if ~isempty(pkgName)
                if any(strcmp(getProcessorPackageList(app), pkgName))
                    node.type = 'processor';
                elseif any(strcmp(getClassifierPackageList(app), pkgName))
                    node.type = 'classifier';
                end
            end

            if strcmpi(node.type,'processor')
                node.func = '';
                if ~isempty(pkgName)
                    node.func = [pkgName '.process'];
                    try
                        node.params = safeSetParam(app, [pkgName '.setparam']);
                    catch
                        if ~isstruct(node.params), node.params = struct(); end
                    end
                    if ~isfield(node.params,'pkg')
                        node.params.pkg = pkgName;
                    end
                end
                node.gui = 'processDataGUI';
                node.paramRequired = {'pkg'};
                node.inputs = {'roiList'};
                node.outputs = {'dataSeries'};

            elseif strcmpi(node.type,'classifier')
                node.func = '';
                if ~isempty(pkgName)
                    row = getClasslistRowByName(app, pkgName);
                    fun = getClassifyFunFromRow(app, row);
                    if ~isempty(fun)
                        node.func = fun;
                    end
                    if ~isfield(node.params,'pkg')
                        node.params.pkg = pkgName;
                    end
                end
                node.gui = 'classifierGUI';
                node.paramRequired = {'pkg'};
                node.inputs = {'roiList'};
                node.outputs = {'dataSeries'};
            end

            app.Data.nodes(idx) = node;
        end

        function col = getModuleColor(app, node)
            reg = getModuleRegistry(app);
            idx = find(strcmpi({reg.type}, node.type), 1);
            if isempty(idx)
                col = [0.7 0.7 0.7];
            else
                col = reg(idx).color;
            end
            if isfield(node,'enabled') && ~node.enabled
                col = col*0.5 + 0.5;
            end
        end

        function [nodes, edges] = unpackPipeline(app, pipeObj)
            nodes = [];
            edges = [];
            if isempty(pipeObj)
                return;
            end
            if isa(pipeObj,'pipeline')
                nodes = pipeObj.nodes;
                edges = pipeObj.edges;
            elseif isstruct(pipeObj)
                if isfield(pipeObj,'nodes'), nodes = pipeObj.nodes; end
                if isfield(pipeObj,'edges'), edges = pipeObj.edges; end
            end

            for i = 1:numel(nodes)
                if ~isfield(nodes(i),'layout') || isempty(nodes(i).layout)
                    nodes(i).layout = [10 10 20 10];
                end
                if ~isfield(nodes(i),'name') || isempty(nodes(i).name)
                    nodes(i).name = nodes(i).id;
                end
                if ~isfield(nodes(i),'enabled')
                    nodes(i).enabled = true;
                end
                if ~isfield(nodes(i),'pkg')
                    nodes(i).pkg = '';
                end
            end
        end

        function pipe = buildPipelineStruct(app, includeDisabled)
            if nargin < 2
                includeDisabled = true;
            end
            pipe = struct();
            pipe.name = 'pipeline_gui';
            pipe.nodes = app.Data.nodes;
            pipe.edges = app.Data.edges;

            if ~includeDisabled
                for i = 1:numel(pipe.nodes)
                    if isfield(pipe.nodes(i),'enabled') && ~pipe.nodes(i).enabled
                        pipe.nodes(i).condition = 'false';
                    end
                end
            end
        end

        function savePipelineFromGUI(app)
            pipeObj = [];
            if isfield(app.Context,'pipeObj') && isa(app.Context.pipeObj,'pipeline')
                pipeObj = app.Context.pipeObj;
            end

            if isempty(pipeObj)
                pipeObj = pipelineNew();
                if isempty(pipeObj)
                    return;
                end
                app.Context.pipeObj = pipeObj;
            end

            pipe = buildPipelineStruct(app, true);
            pipeObj.nodes = pipe.nodes;
            pipeObj.edges = pipe.edges;
            pipelineSave(pipeObj);
        end

        function createDynamicSaveButton(app)
            if isfield(app.Context,'savePipelineButton')
                hb = app.Context.savePipelineButton;
                if ~isempty(hb) && isvalid(hb)
                    return;
                end
            end

            hb = uibutton(app.UIFigure, 'push');
            hb.ButtonPushedFcn = createCallbackFcn(app, @SavepipelineButtonPushed, true);
            hb.Position = [520 759 120 23];
            hb.Text = 'Save pipeline...';
            app.Context.savePipelineButton = hb;
        end

        function modulePatchButtonDown(app, src, event)
            idx = src.UserData;
            if strcmp(app.UIFigure.SelectionType,'open')
                openModule(app, idx);
                return;
            end

            mod = app.UIFigure.CurrentModifier;
            additive = any(strcmp(mod,'shift'));
            setSelection(app, idx, additive);
            updateParamsTable(app, idx);

            pt = app.UIModulesAxes.CurrentPoint;
            node = app.Data.nodes(idx);
            app.DraggingModule = idx;
            app.DragOffset = [pt(1,1) - node.layout(1), pt(1,2) - node.layout(2)];
        end

        function val = getfielddefault(varargin)
            if nargin == 4
                s = varargin{2};
                f = varargin{3};
                default = varargin{4};
            elseif nargin == 3
                s = varargin{1};
                f = varargin{2};
                default = varargin{3};
            else
                val = [];
                return;
            end

            if isstruct(s) && isfield(s,f)
                val = s.(f);
            else
                val = default;
            end
        end

    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIModulesAxes = uiaxes(app.UIFigure);
            zlabel(app.UIModulesAxes, 'Z')
            app.UIModulesAxes.XTick = [];
            app.UIModulesAxes.YTick = [];
            app.UIModulesAxes.ButtonDownFcn = createCallbackFcn(app, @UIModulesAxesButtonDown, true);
            app.ModuletypeDropDownLabel = uilabel(app.UIFigure);
            app.ModuletypeDropDownLabel.Text = 'Module type';
            app.ModuletypeDropDown = uidropdown(app.UIFigure);
            app.ModuletypeDropDown.Items = {'Dataloader', 'ROI identification', 'ROI extraction', 'Processor', 'Classifier'};
            app.ModuletypeDropDown.Value = 'Dataloader';
            app.AddmoduleButton = uibutton(app.UIFigure, 'push');
            app.AddmoduleButton.ButtonPushedFcn = createCallbackFcn(app, @AddmoduleButtonPushed, true);
            app.AddmoduleButton.Text = 'Add module';
            app.UIModuleListTable = uitable(app.UIFigure);
            app.UIModuleListTable.ColumnName = {'Select'; 'Name'; 'Package'; 'Requires'; 'Provides'; 'Status'};
            app.UIModuleListTable.RowName = {};
            app.UIModuleListTable.ColumnEditable = [true true true false false false];
            app.UIModuleListTable.CellEditCallback = createCallbackFcn(app, @UIModuleListTableCellEdit, true);
            app.UIModuleListTable.SelectionChangedFcn = createCallbackFcn(app, @UIModuleListTableSelectionChanged, true);
            app.RunpipelineButton = uibutton(app.UIFigure, 'push');
            app.RunpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunpipelineButtonPushed, true);
            app.RunpipelineButton.Text = 'Run pipeline';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CheckpipelineButton = uibutton(app.UIFigure, 'push');
            app.CheckpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpipelineButtonPushed, true);
            app.CheckpipelineButton.Text = 'Check pipeline';
            app.OpenselectedmoduleButton = uibutton(app.UIFigure, 'push');
            app.OpenselectedmoduleButton.ButtonPushedFcn = createCallbackFcn(app, @OpenselectedmoduleButtonPushed, true);
            app.OpenselectedmoduleButton.Text = 'Open selected module';
            app.CreaterunButton = uibutton(app.UIFigure, 'push');
            app.CreaterunButton.ButtonPushedFcn = createCallbackFcn(app, @CreaterunButtonPushed, true);
            app.CreaterunButton.Text = 'Create run...';
            app.UIModuleParametersTable = uitable(app.UIFigure);
            app.UIModuleParametersTable.ColumnName = {'Parameter'; 'Value'};
            app.UIModuleParametersTable.RowName = {};
            app.UIModuleParametersTable.CellEditCallback = createCallbackFcn(app, @UIModuleParametersTableCellEdit, true);
            app.UIModuleParametersTable.SelectionChangedFcn = createCallbackFcn(app, @UIModuleParametersTableSelectionChanged, true);
            app.ConnectDisconnectmodulesButton = uibutton(app.UIFigure, 'push');
            app.ConnectDisconnectmodulesButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDisconnectmodulesButtonPushed, true);
            app.ConnectDisconnectmodulesButton.Text = 'Connect/Disconnect modules';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pipelineGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end