classdef processDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProcessdataUIFigure           matlab.ui.Figure
        ClassificationoptionsPanel    matlab.ui.container.Panel
        ExecutionenvironmentDropDown  matlab.ui.control.DropDown
        ExecutionenvironmentDropDownLabel  matlab.ui.control.Label
        ProcessallframesoveridesframestableselectionCheckBox  matlab.ui.control.CheckBox
        ParallelROIs                  matlab.ui.control.CheckBox
        ApplyselectedsettingstoallButton  matlab.ui.control.Button
        DeselectallButton             matlab.ui.control.Button
        SelectallButton               matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        ProcessselecteddataButton     matlab.ui.control.Button
        SelectpositionsandenterROIsarrayLabel  matlab.ui.control.Label
        SelectprocessorDropDown       matlab.ui.control.DropDown
        UITable                       matlab.ui.control.Table
        SelectprocessorDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Data % Description
    end

    methods (Access = private)

        function updateTable(app)

            list= app.SelectprocessorDropDown.Value;
            shallowObj=app.Data.shallowObj;

            app.Data.storedobj=[];
            %app.Data.storedobj.data=[];

            % str= app.SelectclassfiermodelDropDown.Value;
            %  list=app.SelectclassfiermodelDropDown.Items;
            valueClassi=find(matches(list,app.SelectprocessorDropDown.Value));

            if numel(valueClassi)==0
                return;
            end

            classilist= app.Data.varstr;
            projectlist= app.Data.tobeclassified_varstr;
            projectnames=app.Data.tobeclassified_projectstr;

            classif=classilist{valueClassi};
            classif=evalin('base',classif);

            % classiid=find(matches(app.SelectclassfiermodelDropDown.Items,str));

            %  shallowObj=app.Data.shallowObj;

            t=app.UITable;
            t.ColumnName={'Select','Project','ROIs from Position or Classifier','Name','Select ROIs array','Select frames'};
            t.ColumnEditable=[true false false false true true];

            %             str2={};
            %
            %             for i=1:numel(projectlist)
            %
            %               shallowObj=evalin('base',projectlist{i});
            %
            %              if numel(shallowObj.fov(i).roi(1).id)
            %                  tmp= shallowObj.fov(i).roi(1).display.channel;
            %                  str2=unique([tmp,str2],'stable');
            %              end
            %             end

            t.ColumnFormat={[] [] [] [] [] [] []};

            Data={};

            cc=1;
            for i=1:numel(projectlist)
                strcha='';

                if numel(projectlist{i})==0
                    continue
                end

                obj= evalin('base',projectlist{i});

                if isa(obj,'process')

                    if numel(app.Data.specificobj)
                        if app.Data.specificobj~= obj % subselect specific classi
                            continue
                        end
                    end

                else % check that fov belongs to the right project
                    if numel(projectnames{i})
                    projn=evalin('base',projectnames{i});
                    if app.Data.specificobj~=projn
                        continue
                    end
                    end 
                end

                app.Data.storedobj(cc).data=obj;

                %                 if numel(obj.roi(1).id)
                %                     cha=obj.roi(1).display.channel;
                %                   % txt= shallowObj.processing.classification(1).channelName;
                %
                %                   if ischar(classif.channelName)
                %                      classif.channelName={classif.channelName};
                %                   end
                %
                %                   for j=1:numel(classif.channelName)
                %
                %                   query= classif.channelName{j};
                %
                %                    if numel(find(matches(cha,query)))
                %
                %                        strcha=[strcha classif.channelName{j} ','];
                %                    else
                %                        aa=obj.roi(1).display.channel;
                %                        if ischar(aa)
                %                           strcha=[aa ','];
                %                        else
                %                           strcha=[obj.roi(1).display.channel{1},','];
                %
                %                        end
                %
                %                    end
                %                   end
                %                 end
                % strcha

                %strcha=strcha(1:end-1);

                fra=[];

                if isfield(obj,'frames')
                    if numel(obj.frames)>1
                        pix=find(matches(obj.channel,strcha));
                        if numel(pix)
                            fra=obj.frames(pix);
                        else
                            fra=obj.frames(1);
                        end
                    end
                end

                if numel(fra)==0
                    fra=getObjectFrameCount(app,obj);
                end

                roilist=['1:' num2str(numel(obj.roi))];

                %                if isa(obj,'classi')
                %                    id=obj.strid;
                %                    typ='Classifier';
                %
                %                    if app.Data.validation==1 % this is a classfier to validate, hence take only the validation ROIs
                %
                %                          roilist=num2str(setxor(1:numel(obj.roi),obj.trainingset));
                %                    end
                %                end
                if isa(obj,'fov')
                    id=obj.id;
                    typ='FOV';
                end
                if isa(obj,'classi')
                    id=obj.strid;
                    typ='Classifier';
                end


                Data(cc,:)={true projectnames{i} typ id roilist ['1:' num2str(fra)]};
                cc=cc+1;
            end

            t.Data=Data;


            %             % HERE update function for display
            %
            %             str= app.SelectprocessorDropDown.Value;
            %             shallowObj=app.Data.shallowObj;
            %             t=app.UITable;
            %             t.ColumnName={'Select','Position or Classifier ROIs','Name','Select ROIs array','Select frames'};
            %             t.ColumnEditable=[true false false true true];
            %
            %             t.ColumnFormat={[] [] [] [] []};
            %
            %             Data={};
            %
            %             for i=1:numel(shallowObj.fov)
            %
            %                 if numel(shallowObj.fov(i).frames)>1
            %                     % pix=find(matches(shallowObj.fov(i).channel,strcha));
            %                     fra=shallowObj.fov(i).frames(1);
            %                 else
            %                     fra=shallowObj.fov(i).frames;
            %                 end
            %
            %                 Data(i,:)={true num2str(i) shallowObj.fov(i).id ['1:' num2str(numel(shallowObj.fov(i).roi))] ['1:' num2str(fra)]};
            %             end
            %
            %             t.Data=Data;
        end

        function fra=getObjectFrameCount(app,obj)
            fra=[];

            if isprop(obj,'frames') && ~isempty(obj.frames)
                fra=obj.frames(1);
                return;
            end

            if isprop(obj,'roi') && ~isempty(obj.roi) && numel(obj.roi(1).id)>0
                fra=getRoiFrameCount(app,obj.roi(1));
            end

            if numel(fra)==0
                fra=1;
            end
        end

        function fra=getRoiFrameCount(app,roiObj)
            fra=[];

            try
                [h5File,exists]=roiObj.getH5Filename();
            catch
                try
                    h5File=fullfile(roiObj.path,['im_' roiObj.id '.h5']);
                    exists=isfile(h5File);
                catch
                    exists=false;
                end
            end

            if ~exists
                return;
            end

            try
                info=h5info(h5File,'/');
            catch
                return;
            end

            candidates=[];
            for d=1:numel(info.Datasets)
                dsPath=['/' info.Datasets(d).Name];
                try
                    frames=h5readatt(h5File,dsPath,'frames');
                    candidates(end+1)=numel(frames);
                    continue;
                catch
                end
                try
                    candidates(end+1)=double(h5readatt(h5File,dsPath,'abs_T'));
                    continue;
                catch
                end
                try
                    dims=double(info.Datasets(d).Dataspace.Size);
                    if numel(dims)>=4
                        candidates(end+1)=dims(4);
                    end
                catch
                end
            end

            if ~isempty(candidates)
                fra=max(candidates);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj)

            if nargin==1
                shallowObj=[];
            end

            app.Data.shallowObj=shallowObj;

            try
                if isa(shallowObj,'shallow')
                    app.Data.availableChannels=listAvailableChannels(shallowObj,'AllowWorkspaceScan',false);
                else
                    app.Data.availableChannels=listAvailableChannels();
                end
            catch
                app.Data.availableChannels={};
            end

            app.Data.st=gatherVariablesFromWorkspace;

            s=app.Data.st;
            cc=1;
            store=[];
            displaystr={};
            varstr={};
            tobeclassified_varstr={};
            tobeclassified_projectstr={};
            cd=1;

            % list avaialable fovs and classi with ROIs
            app.Data.specificobj=[];

            for i=1:numel(s.Project)

                proj=s.Project{i};

                if shallowObj==evalin('base',proj)
                    app.Data.specificobj=shallowObj; % project variable is provdided as input
                end

                for k=1:numel(s.Projectpos{i})

                    %     if isa(shallowObj,'classi') % if classi is provided as inout, then skip project pos
                    %       continue
                    % end

                    tmp= evalin('base',[proj '.fov(' num2str(k) ')']);

                    if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                        tobeclassified_varstr{cd}=[proj '.fov(' num2str(k) ')'];
                        tobeclassified_projectstr{cd}=proj;

                        % tobeclassified_projectstr

                        % displaystr{cc}=[proj '  //  ' s.Projectpos{i}{k}];
                        if numel(store)==0
                            store=numel(tmp.roi);
                        end
                        cd=cd+1;
                        %  cc=cc+1;
                    end
                end

                for k=1:numel(s.Projectclassi{i})

                    tmp= evalin('base',[proj '.processing.classification(' num2str(k) ')']);

                    % if shallowObj==tmp
                    % app.Data.specificobj=shallowObj; % classi variable from project is provided
                    % else
                    %     if isa(shallowObj,'classi') % remove all other classi is classi obj is provided
                    %         continue
                    %     else            % if project is provided with a specific classifier as input, then discard all other classifer
                    %         if tmp~=classiObj
                    %             continue
                    %         end
                    %
                    %
                    %     end
                    % end

                    % if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                    %varstr{cc}=[proj '.processing.classification(' num2str(k) ')'];

                    tobeclassified_varstr{cd}=[proj '.processing.classification(' num2str(k) ')'];
                    tobeclassified_projectstr{cd}=proj;
                    %      displaystr{cc}=[proj '  //  ' s.Projectclassi{i}{k}];

                    if numel(store)==0
                        store=numel(tmp.roi);
                    end
                    %   cc=cc+1;
                    cd=cd+1;
                    % end
                end

                for k=1:numel(s.Projectprocessor{i})

                    tmp= evalin('base',[proj '.processing.processor(' num2str(k) ')']);

                    if shallowObj==tmp
                        app.Data.specificobj=shallowObj; % classi variable from project is provided
                    else
                        if isa(shallowObj,'process') % remove all other classi is classi obj is provided
                            continue
                        else            % if project is provided with a specific classifier as input, then discard all other classifer
                            %                                 if tmp~=classiObj;
                            %                                     continue
                            %                                 end


                        end
                    end

                    % if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                    varstr{cc}=[proj '.processing.processor(' num2str(k) ')'];

                    %tobeclassified_varstr{cd}=[proj '.processing.processor(' num2str(k) ')'];
                    %tobeclassified_projectstr{cd}=proj;

                    %tobeclassified_projectstr

                    displaystr{cc}=[proj '  //  ' s.Projectprocessor{i}{k}];

                    if numel(store)==0
                      %  store=numel(tmp.roi);
                      store=[];
                    end
                    cc=cc+1;
                    cd=cd+1;
                    % end
                end


            end

            for i=1:numel(s.Classifier)

                clas=s.Classifier{i};

                tmp= evalin('base',clas);

                % if shallowObj==tmp
                %    app.Data.specificobj=shallowObj;
                % else
                %    if isa(shallowObj,'classi') % remove all other classi is classi obj is provided
                %    continue
                %    else            % if project is provided with a specific classifier as input, then discard all other classifer
                %            if tmp~=classiObj
                %                continue
                %            end
                %
                %    end
                % end

                %if numel(tmp.roi)>0 & numel(tmp.roi(1).id)>0
                varstr{cc}=clas;

                %   displaystr{cc}=clas;
                tobeclassified_varstr{cd}=clas;
                tobeclassified_projectstr{cd}='';
                if numel(store)==0
                    store=numel(tmp.roi);
                end
                cd=cd+1;
                cc=cc+1;
                % end

            end

            %   displaystr

            % str={};
            %       for i=1:numel(shallowObj.processing.processor)
            %           str{i}=shallowObj.processing.processor(i).strid;
            %       end
            %       app.SelectprocessorDropDown.Items=str;


            %   app.SelectprocessorDropDown.Items=str;

            %displaystr
            app.SelectprocessorDropDown.Items=displaystr';
            app.Data.varstr=varstr;
            app.Data.tobeclassified_varstr=tobeclassified_varstr; % list of classifier variables in the workspace
            app.Data.tobeclassified_projectstr=tobeclassified_projectstr;
            app.Data.displaystr=displaystr;

            updateTable(app);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: ProcessdataUIFigure
        function ProcessdataUIFigureCloseRequest(app, event)
            delete(app)

        end

        % Value changed function: SelectprocessorDropDown
        function SelectprocessorDropDownValueChanged(app, event)
            value = app.SelectprocessorDropDown.Value;

            shallowObj=app.Data.shallowObj;

            %  pix=find(contains(app.SelectprocessorDropDown.Items,value));

            updateTable(app)

            %             dat=app.UITable.Data;
            %
            %             dat{:,4}=num2str(shallowObj.processing.classification(pix).channel);
            %
            %             app.UITable.Data=dat;
        end

        % Button pushed function: ProcessselecteddataButton
        function ProcessselecteddataButtonPushed(app, event)
           % shallowObj=app.Data.shallowObj;

             list=app.SelectprocessorDropDown.Items;
             valueClassi=find(matches(list,app.SelectprocessorDropDown.Value));
             
             classilist= app.Data.varstr;

             classif=classilist{valueClassi};
             classif=evalin('base',classif);
             
            data=app.UITable.Data;

            selpos=find(cellfun(@(x) x==1,data(:,1)));

            data=app.UITable.Data;
          
             frames={};
             channel={};
             
             roiobj=[];
             cc=1;

             for i=1:numel(selpos)
      
                 obj=app.Data.storedobj(selpos(i)).data;

                 tmp=str2num(data{selpos(i),5});

                 if sum(ismember(tmp,1:numel(obj.roi)))==numel(tmp)
                  
             
                     roiobj=[roiobj obj.roi(tmp)];
               
                     txt=textscan(data{selpos(i),4},'%s','Delimiter',',');

                    str={};
                    
                    for j=1:numel(txt{1})
                        str{j}=txt{1}{j};
                    end
                    
                     
                     %pix=find(matches(shallowObj.fov(selpos(i)).roi(1).display.channel,data{selpos(i),4}))
                     for j=1:numel(tmp)
                          
                          if app.ProcessallframesoveridesframestableselectionCheckBox.Value==1
                           frames{cc}=-1;
                          else
                          frames{cc}=str2num(data{selpos(i),6});
                          end
                         
                         cc=cc+1;
                     end
                 end
             end

             d = uiprogressdlg(app.ProcessdataUIFigure,'Title','Please Wait...',...
                'Message','Starting data classification...');
            d.Value=0.01;

            arg={'Progress',d,'Frames',frames};

             if app.ParallelROIs.Value==true
                arg=[arg {'Parallel'}];
            end

              if app.ExecutionenvironmentDropDown.Value=="CPU"
                arg=[arg {'CPU'}];
              end
               if app.ExecutionenvironmentDropDown.Value=="GPU"
                arg=[arg {'GPU'}];
               end


              tic
             processData(classif,roiobj,arg{:});
              toc
            %  channel
            % roiscell


          %  shallowObj.processData(valueClassi,'Fovs',selpos,'Rois',roiscell,'Progress',d,'Frames',frames);

            %  uialert(app.ProcessdataUIFigure,'Don t forget to save the project!!','Warning');

        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            data=app.UITable.Data;

            data(:,1)={true};

            app.UITable.Data=data;
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            data=app.UITable.Data;

            data(:,1)={false};

            app.UITable.Data=data;
        end

        % Button pushed function: ApplyselectedsettingstoallButton
        function ApplyselectedsettingstoallButtonPushed(app, event)
            data=app.UITable.Data;

            selectedfovs=find(cellfun(@(x) x==1,data(:,1)));

            if numel(selectedfovs)==0
                uialert(app.ProcessdataUIFigure,'No position was selected','Error');
            end

            for i=1:size(data,1)

                if i~=selectedfovs(1)

                    data(i,4:end)=data(selectedfovs(1),4:end);

                end
            end

            app.UITable.Data=data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProcessdataUIFigure = uifigure('Visible', 'off');
            app.ProcessdataUIFigure.Name = 'Process data';
            app.ProcessdataUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProcessdataUIFigureCloseRequest, true);
            app.SelectprocessorDropDownLabel = uilabel(app.ProcessdataUIFigure);
            app.SelectprocessorDropDownLabel.Text = 'Select processor: ';
            app.UITable = uitable(app.ProcessdataUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectprocessorDropDown = uidropdown(app.ProcessdataUIFigure);
            app.SelectprocessorDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectprocessorDropDownValueChanged, true);
            app.SelectpositionsandenterROIsarrayLabel = uilabel(app.ProcessdataUIFigure);
            app.SelectpositionsandenterROIsarrayLabel.Text = 'Select positions and enter ROIs array:';
            app.ProcessselecteddataButton = uibutton(app.ProcessdataUIFigure, 'push');
            app.ProcessselecteddataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessselecteddataButtonPushed, true);
            app.ProcessselecteddataButton.Text = 'Process selected data';
            app.CloseButton = uibutton(app.ProcessdataUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectallButton = uibutton(app.ProcessdataUIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.ProcessdataUIFigure, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.ApplyselectedsettingstoallButton = uibutton(app.ProcessdataUIFigure, 'push');
            app.ApplyselectedsettingstoallButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyselectedsettingstoallButtonPushed, true);
            app.ApplyselectedsettingstoallButton.Text = 'Apply selected settings to all';
            app.ClassificationoptionsPanel = uipanel(app.ProcessdataUIFigure);
            app.ClassificationoptionsPanel.Title = 'Classification options';
            app.ParallelROIs = uicheckbox(app.ClassificationoptionsPanel);
            app.ParallelROIs.Text = 'Parallel computing';
            app.ProcessallframesoveridesframestableselectionCheckBox = uicheckbox(app.ClassificationoptionsPanel);
            app.ProcessallframesoveridesframestableselectionCheckBox.Text = 'Process all frames (overides frames table selection)';
            app.ExecutionenvironmentDropDownLabel = uilabel(app.ClassificationoptionsPanel);
            app.ExecutionenvironmentDropDownLabel.Text = 'Execution environment';
            app.ExecutionenvironmentDropDown = uidropdown(app.ClassificationoptionsPanel);
            app.ExecutionenvironmentDropDown.Items = {'CPU', 'GPU'};
            app.ExecutionenvironmentDropDown.Value = 'CPU';
            % Show the figure after all components are created
            app.ProcessdataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processDataGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProcessdataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProcessdataUIFigure)
        end
    end
end