classdef processGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProcessorUIFigure              matlab.ui.Figure
        ResetprocessorargumentsButton  matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        SaveparametersButton           matlab.ui.control.Button
        ProcessorparametersPanel       matlab.ui.container.Panel
        ProcessordefintionPanel        matlab.ui.container.Panel
        CustomfunctionEditField        matlab.ui.control.EditField
        CustomfunctionEditFieldLabel   matlab.ui.control.Label
        CatDropDown                    matlab.ui.control.DropDown
        ProcessingcategoryLabel        matlab.ui.control.Label
        MetDropDown                    matlab.ui.control.DropDown
        ProcessingmethodLabel          matlab.ui.control.Label
        PathLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
        mainApp
    end
    
    properties (Access = public)

    end
    
    methods (Access = private)
        
        function displayParam(app)
            
            classiObj=app.Data.processObj; 
            
             app.PathLabel.Text=[ classiObj.path  ' ' classiObj.strid];
            
            processlist=app.Data.processlist; 
            
            processor= classiObj.processFun;
            processorBase=regexprep(char(string(processor)),'\.process$','');
            pix=find(matches(processlist{:,5},processorBase));
            if numel(pix)~=0
                processor=processlist{pix(1),5}{1};
                classiObj.processFun=processor;
            end
            
            app.CatDropDown.Items=[unique(processlist{:,4}); 'Custom'];
            
            app.CustomfunctionEditField.Value='';
            app.CustomfunctionEditField.Enable='off';
           
            if numel(pix)==0
                app.CatDropDown.Value='Custom';
                app.MetDropDown.Items={'Custom'};
                app.MetDropDown.Value='Custom';
                app.CustomfunctionEditField.Enable='on';
                if numel(processor)
                    app.CustomfunctionEditField.Value=processor;
                end
            else
                app.CatDropDown.Value=processlist{pix,4}; 
                
                pix2= find(matches(processlist{:,4},app.CatDropDown.Value));
                pix2=pix2';
                a=processlist{pix2,2};
                app.MetDropDown.Items=a';
                app.MetDropDown.Value=processlist{pix,2};
            end
           
            
            if numel(classiObj.processArg)==0 & numel(classiObj.processFun)~=0
                
                param=feval(classiObj.processFun);
                classiObj.processArg=param;
            else
                 param=classiObj.processArg;
            end
            
           % app.Data.selected=[];
           app.ProcessorparametersPanel.Enable='off';
           
            if numel(param)
                for i=1:numel(app.ProcessorparametersPanel.Children)
                    delete(app.ProcessorparametersPanel.Children(1))
                end
            app.ProcessorparametersPanel.Enable='on';

            if isfield(param,'tip')
            tip=param.tip;
            param=rmfield(param,'tip');
            struct2GUI(param,[30 380],'Handle',app.ProcessorparametersPanel,'Tip',tip,'Width',200);
            param.tip=tip;
            else
            struct2GUI(param,[30 380],'Handle',app.ProcessorparametersPanel,'Width',200);
            end


            end
        
        end
        
%         function checkstatus(app,saving)
%             % checks whether classi parameters are saved and classifier is
%             % in memory
%            
%             % checks whether paramas have changed
%             if nargin==2
%           
%                if saving==false
%                    app.StatusSaved.Color=[1 0 0];
%                else
%                    app.StatusSaved.Color=[0 1 0];
%                end
%             end
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classiObj, mainApp)
             if nargin==1
                classiObj=processor;
                classiObj.path='';
                classiObj.strid='No input classi was provided';
            end

            app.ProcessorUIFigure.Name=[classiObj.strid];
            app.Data.processObj=classiObj;

            str=which('shallowNew.m');
            [pth file ext]=fileparts(str);
            str=[pth '/processor/processlist.mat'];
            load(str);
            app.Data.processlist=processlist;
            
            if nargin==3
                    app.mainApp=mainApp;
            end

            displayParam(app)
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            disp('Saving processor')

            % gather parameter values from input panel 
            
            param=app.ProcessorparametersPanel.UserData;
            app.Data.processObj.processArg=param;
            
             if ~isempty(app.mainApp)
               app.mainApp.processObj= app.Data.processObj;

             end

            d = uiprogressdlg(app.ProcessorUIFigure,'Title','Please Wait...',...
                'Message','Saving current processor parameters...');
            d.Value=0.33;

            processSave(app.Data.processObj);


            d.Value=0.67;
            d.Message='Saving training parameters...';
            
            close(d)
        end

        % Value changed function: MetDropDown
        function MetDropDownValueChanged(app, event)
            value = app.MetDropDown.Value;
            processlist=app.Data.processlist; 
            
            pix=find(matches(processlist{:,2},value));
            
             if numel(pix)==0 % custom mode
                app.Data.processObj.processFun='';
            else
                app.Data.processObj.processFun=processlist{pix(1),5}{1};
            end
            
            app.Data.processObj.processArg={};
            displayParam(app)
        end

        % Value changed function: CatDropDown
        function CatDropDownValueChanged(app, event)
            value = app.CatDropDown.Value;
            processlist=app.Data.processlist; 
            
            pix=find(matches(processlist{:,4},value));
            
            if numel(pix)==0 % custom mode
                app.Data.processObj.processFun='';
            else
                app.Data.processObj.processFun=processlist{pix(1),5}{1};
            end
            
          
            app.Data.processObj.processArg={};
            
            displayParam(app);
        end

        % Value changed function: CustomfunctionEditField
        function CustomfunctionEditFieldValueChanged(app, event)
            value = app.CustomfunctionEditField.Value;
            
            if exist(value)==2
                
                try
                partest=feval(value);
                catch 
                value='';
                uialert(app.ProcessorUIFigure,'The processing function is not properly formatted','Error');
                end
                
            app.Data.processObj.processFun=value;
            else
               uialert(app.ProcessorUIFigure,'This function does not exist in MATLAB path','Error');
               app.Data.processObj.processFun='';
              
            end
            
             app.Data.processObj.processArg={};
            
            displayParam(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
             if ~isempty(app.mainApp)
                uiresume(app.mainApp.LifespanizerUIFigure)
             end
             
             delete(app.ProcessorUIFigure)
        end

        % Close request function: ProcessorUIFigure
        function ProcessorUIFigureCloseRequest(app, event)
           
            if ~isempty(app.mainApp)
                uiresume(app.mainApp.LifespanizerUIFigure)
            end

            delete(app)
            
        end

        % Button pushed function: ResetprocessorargumentsButton
        function ResetprocessorargumentsButtonPushed(app, event)
                app.Data.processObj.processArg={};
               %  app.Data.processObj.processFun={};
                displayParam(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProcessorUIFigure = uifigure('Visible', 'off');
            app.ProcessorUIFigure.Name = 'Processor';
            app.ProcessorUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProcessorUIFigureCloseRequest, true);
            app.ProcessordefintionPanel = uipanel(app.ProcessorUIFigure);
            app.ProcessordefintionPanel.Title = 'Processor defintion';
            app.PathLabel = uilabel(app.ProcessordefintionPanel);
            app.PathLabel.Text = 'Path';
            app.ProcessingmethodLabel = uilabel(app.ProcessordefintionPanel);
            app.ProcessingmethodLabel.Text = 'Processing method:';
            app.MetDropDown = uidropdown(app.ProcessordefintionPanel);
            app.MetDropDown.ValueChangedFcn = createCallbackFcn(app, @MetDropDownValueChanged, true);
            app.ProcessingcategoryLabel = uilabel(app.ProcessordefintionPanel);
            app.ProcessingcategoryLabel.Text = 'Processing category:';
            app.CatDropDown = uidropdown(app.ProcessordefintionPanel);
            app.CatDropDown.ValueChangedFcn = createCallbackFcn(app, @CatDropDownValueChanged, true);
            app.CustomfunctionEditFieldLabel = uilabel(app.ProcessordefintionPanel);
            app.CustomfunctionEditFieldLabel.Text = 'Custom function:';
            app.CustomfunctionEditField = uieditfield(app.ProcessordefintionPanel, 'text');
            app.CustomfunctionEditField.ValueChangedFcn = createCallbackFcn(app, @CustomfunctionEditFieldValueChanged, true);
            app.ProcessorparametersPanel = uipanel(app.ProcessorUIFigure);
            app.ProcessorparametersPanel.Title = 'Processor parameters';
            app.SaveparametersButton = uibutton(app.ProcessorUIFigure, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.CloseButton = uibutton(app.ProcessorUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ResetprocessorargumentsButton = uibutton(app.ProcessorUIFigure, 'push');
            app.ResetprocessorargumentsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetprocessorargumentsButtonPushed, true);
            app.ResetprocessorargumentsButton.Text = 'Reset processor arguments';
            % Show the figure after all components are created
            app.ProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = processGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProcessorUIFigure)
        end
    end
end