classdef roiImporterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImportROIsfromdiskButton        matlab.ui.control.Button
        ImportedROischannelselectionPanel  matlab.ui.container.Panel
        ChannelListBox                  matlab.ui.control.ListBox
        SelectROIstoimportLabel         matlab.ui.control.Label
        ImportedROIsclassesmappingPanel  matlab.ui.container.Panel
        ClassesinthesourceROIEditField  matlab.ui.control.EditField
        ClassesinthesourceROIEditFieldLabel  matlab.ui.control.Label
        ClassesinthedestinationROIEditField  matlab.ui.control.EditField
        ClassesinthedestinationROIEditFieldLabel  matlab.ui.control.Label
        Preserveannotations             matlab.ui.control.CheckBox
        ImportedROIschannelnamemappingPanel  matlab.ui.container.Panel
        sourceChannelList               matlab.ui.control.ListBox
        ClickselectchannelsusedasinputoftheclassifierLabel  matlab.ui.control.Label
        WARNNGLabel                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        sourceChannelList_2             matlab.ui.control.ListBox
        ChannelsavailableinROIstobeimportedLabel  matlab.ui.control.Label
        DeselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TypeROIindicestoimportEditField  matlab.ui.control.EditField
        TypeROIindicestoimportEditFieldLabel  matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        ProceedButton                   matlab.ui.control.Button
        ChoosePositionClassiferDropDown  matlab.ui.control.DropDown
        ChoosePositionClassiferDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Data % Description
    end

    methods (Access = private)


        function  displayChannels(app)
            classiObj=app.Data.mainApp.Data.classiObj;
              app.ImportedROIschannelnamemappingPanel.Enable="on";

            selection = app.UITable.Selection;

            if numel(selection)==0
                return
            end

            pixtable=selection(1,1);

            target=app.Data.target{pixtable};

            targetChannel=app.Data.targetChannel{pixtable};

            if numel(classiObj.channelName)
                app.sourceChannelList.Items= classiObj.channelName;
               
            else
                app.sourceChannelList.Items={};
                 app.ImportedROIschannelnamemappingPanel.Enable="off";
            end

            if numel(app.sourceChannelList.Value)==0
                if numel(app.sourceChannelList.Items)
                    app.sourceChannelList.Value= app.sourceChannelList.Items{1};
                else
                    app.sourceChannelList.Value={};
                end
            end

            str=target.roi(1).display.channel;
            if numel(str)
                if ischar(str)
                    str={str};
                end
                app.sourceChannelList_2.Items=str;
            else
                app.sourceChannelList_2.Items={};
            end

       
            selectedChannel=find(matches(app.sourceChannelList.Items,app.sourceChannelList.Value));

          
            val={};

            if numel(targetChannel)~=0 && numel(targetChannel{1})==0
                pix=find(matches(str,app.sourceChannelList.Value));
                if numel(pix)
                    val=str{pix};
                else

                end
            else

                %aa=app.Data.targetChannel
                % selectedChannel
                if numel(selectedChannel)~=0
                val=  app.Data.targetChannel{pixtable}{selectedChannel};
                end
            end
            if numel(val)==0
                val=app.sourceChannelList_2.Items{1};
            end

            if ischar(val)
                val={val};
            end

            if numel(val)
                app.sourceChannelList_2.Value=val;
                if numel(selectedChannel)
                    app.Data.targetChannel{pixtable}{selectedChannel}=app.sourceChannelList_2.Value;
                end
            end

%             nrois=classiObj.roi; 
%             if nrois==1 && numel(classiObj.roi(1).id)==0
%                 nrois=0;
%             end
%             if nrois==0
               
           % end
        end
        
        function  displayAnnotations(app)

       
            selection = app.UITable.Selection;

            if numel(selection)==0
                return
            end

            pix=selection(1,1);

            if numel(pix)==0
                return
            end
                %   dat = app.ChoosePositionClassiferDropDown.Value;
         %   pix=contains(app.Data.displaystr,dat);

            va=app.Data.varstr{pix};
            tmp=evalin('base',va);

            cla=false;

            if isa(tmp,'classi')
                aa=tmp.classes;

                if numel(aa)
                    cla=true;
                end

            end


             if isa(tmp,'classi')
                app.Preserveannotations.Enable='on';

                if app.Data.preserve{pix}==true
                        app.Preserveannotations.Value=true;
                else
                   app.Preserveannotations.Value=false;
                end
            else
                app.Preserveannotations.Enable='off';
                app.Preserveannotations.Value=false;
                app.Data.preserve{pix}=false;
            end

              value = app.Preserveannotations.Value;


            %  aa=tmp.classes

            if value && cla % preserve training
                app.ClassesinthedestinationROIEditField.Enable='on';
                app.ClassesinthesourceROIEditField.Enable='on';

                if  numel(app.Data.convert{pix})==0
                str=[];
                for i=1:numel(tmp.classes)
                    str=[str ' ' tmp.classes{i}];
                end

                str2=[];
                for i=1:numel(app.Data.mainApp.Data.classiObj.classes)
                    str2=[str2 ' ' app.Data.mainApp.Data.classiObj.classes{i}];
                end

                app.ClassesinthesourceROIEditField.Value=str;
                app.ClassesinthedestinationROIEditField.Value=str2;
                app.Data.convert{pix}={str,str2};
                else
                app.ClassesinthesourceROIEditField.Value=app.Data.convert{pix}{1};
                app.ClassesinthedestinationROIEditField.Value=app.Data.convert{pix}{2};
                end

            else
                app.ClassesinthedestinationROIEditField.Enable='off';
                app.ClassesinthesourceROIEditField.Enable='off';
                app.Data.convert{pix}={};
            end

        end
        
        function displayChannelsImport(app)
                selection = app.UITable.Selection;

            if numel(selection)==0
                return
            end

            pix=selection(1,1);

            if numel(pix)==0
                return
            end

         items = app.Data.channelsAvailable{pix};
 if ~iscell(items) 
     items = {items}; % dé-neste
 end

%items  = app.Data.channelsAvailable(pix)

app.ChannelListBox.Items = items;


          %  app.ChannelListBox.Items=app.Data.channelsAvailable{pix};

  app.ChannelListBox.Value=app.Data.channelsToImport{pix};

        end
        
        function displayROIs(app,option)

            if nargin==1
                option=[];
            end

            s=app.Data.st;
            cc=1;
            store=[];
            displaystr={};
            annotate={};
            channels={};

            mainApp=app.Data.mainApp;


           for i=1:numel(s.Project)

    proj = s.Project{i};
    shallowObj = evalin('base',proj);

    if numel(s.Projectpos)
        for k=1:numel(s.Projectpos{i})

            tmp = evalin('base',[proj '.fov(' num2str(k) ')']);

            if numel(tmp.roi) > 0 && numel(tmp.roi(1).id) > 0
                varstr{cc} = [proj '.fov(' num2str(k) ')'];
                displaystr{cc} = [proj '  //  ' s.Projectpos{i}{k} ' - ' num2str(numel(tmp.roi)) ' ROIs available'];
                displaystr2{cc,1} = proj;
                displaystr2{cc,2} = s.Projectpos{i}{k};
                displaystr2{cc,3} = ['1:' num2str(numel(tmp.roi)) ];
                app.Data.convert{cc} = {};
                app.Data.preserve{cc} = true;
                app.Data.targetChannel{cc} = cell(1,numel(mainApp.Data.classiObj.channelName));
                app.Data.target{cc} = tmp;

                % 🔹 Parcourt toutes les ROIs pour collecter tous les channels
                allChannels = {};
                for r = 1:numel(tmp.roi)
                    ch = tmp.roi(r).display.channel;
                    if ischar(ch)
                        ch = {ch};
                    elseif isstring(ch)
                        ch = cellstr(ch);
                    end
                    allChannels = [allChannels, ch];
                end
                app.Data.channelsToImport{cc} = unique(allChannels, 'stable');

                if isempty(store)
                    store = numel(tmp.roi);
                end
                cc = cc + 1;
            end
        end
    end

    if numel(s.Projectclassi)
        for k=1:numel(s.Projectclassi{i})
            val = k;
            if nargin == 3
                mp = evalin('base',proj);
                for l = 1:numel(mp.processing.classification)
                    if strcmp(mp.processing.classification(l).strid,option)
                        break
                    end
                end
                val = l;
            end

            tmp = evalin('base',[proj '.processing.classification(' num2str(val) ')']);

            if numel(tmp.roi) > 0 && numel(tmp.roi(1).id) > 0
                varstr{cc} = [proj '.processing.classification(' num2str(val) ')'];
                displaystr{cc} = [proj '  //  ' s.Projectclassi{i}{k} ' - ' num2str(numel(tmp.roi)) ' ROIs available'];
                displaystr2{cc,1} = proj;
                displaystr2{cc,2} = s.Projectclassi{i}{k};
                displaystr2{cc,3} = ['1:' num2str(numel(tmp.roi)) ];
                app.Data.convert{cc} = {};
                app.Data.preserve{cc} = true;
                app.Data.targetChannel{cc} = cell(1,numel(mainApp.Data.classiObj.channelName));
                app.Data.target{cc} = tmp;

                % 🔹 Collecte des channels sur toutes les ROIs
                allChannels = {};
                for r = 1:numel(tmp.roi)
                    ch = tmp.roi(r).display.channel;
                    if ischar(ch)
                        ch = {ch};
                    elseif isstring(ch)
                        ch = cellstr(ch);
                    end
                    allChannels = [allChannels, ch];
                end
                app.Data.channelsToImport{cc} = unique(allChannels, 'stable');

                if isempty(store)
                    store = numel(tmp.roi);
                end
                cc = cc + 1;
            end
        end
    end
end

for i = 1:numel(s.Classifier)
    clas = s.Classifier{i};

    % Sauter les entrées vides
    if isempty(clas)
        continue;
    end

    % Normaliser en char
    if isstring(clas)
        if ~isscalar(clas)
            % on prend le premier élément si jamais c'est un string array
            clas = clas(1);
        end
        clas = char(clas);
    end

    % Si ce n'est toujours pas une char, on saute
    if ~ischar(clas)
        warning('displayROIs:ClassifierType',...
            'Entrée s.Classifier{%d} non textuelle, ignorée.', i);
        continue;
    end

    % Essayer de récupérer la variable dans le base workspace
    try
        tmp = evalin('base', clas);
    catch ME
        warning('displayROIs:EvalinFailed',...
            'Impossible de trouver la variable "%s" dans le workspace (idx %d).', clas, i);
        continue;
    end

    if numel(tmp.roi) > 0 && numel(tmp.roi(1).id) > 0
        varstr{cc}      = clas;
        displaystr{cc}  = [clas ' - ' num2str(numel(tmp.roi)) ' ROIs available'];
        displaystr2{cc,1} = '';
        displaystr2{cc,2} = clas;
        displaystr2{cc,3} = ['1:' num2str(numel(tmp.roi)) ];
        app.Data.convert{cc}        = {};
        app.Data.preserve{cc}       = true;
        app.Data.targetChannel{cc}  = cell(1, numel(mainApp.Data.classiObj.channelName));
        app.Data.target{cc}         = tmp;

        % 🔹 Collecte des channels sur toutes les ROIs
        allChannels = {};
        for r = 1:numel(tmp.roi)
            ch = tmp.roi(r).display.channel;
            if ischar(ch)
                ch = {ch};
            elseif isstring(ch)
                ch = cellstr(ch);
            end
            allChannels = [allChannels, ch]; %#ok<AGROW>
        end
        app.Data.channelsToImport{cc} = unique(allChannels, 'stable');

        if isempty(store)
            store = numel(tmp.roi);
        end
        cc = cc + 1;
    end
end


            app.Data.channelsAvailable=app.Data.channelsToImport;

            %app.ChoosePositionClassiferDropDown.Items=displaystr';
            app.Data.varstr=varstr; % list of classifier variables in the workspace
            app.Data.displaystr=displaystr;

          %  app.TypeROIindicestoimportEditField.Value=['1:' num2str(store)];

         %   ChoosePositionClassiferDropDownValueChanged(app)
            % displayChannels(app)

            t=app.UITable;
            t.Data=[];
            
           %t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};

            t.ColumnWidth={50, 250, 200, 'auto' };
            t.ColumnEditable=[true false false true];
            d=t.Data;

            for i=1:size(displaystr2,1)
                d{i,1}=false;

                if numel(option) && i==1% when subselecting a target classi, preselect it
                 d{i,1}=true;
                end 
            
                d{i,2}=displaystr2{i,1};
                d{i,3}=displaystr2{i,2};
                d{i,4}=displaystr2{i,3};
            end

            t.Data=d;

            app.Data.displaystr2=displaystr2;

            if nargin==3 % incase a classiObj is targeted

             app.UITable.Selection=[1 4];
           UITableSelectionChanged(app)
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, option)


            if nargin~=3
                option=[];
            end

            app.Data.mainApp=mainApp;
            app.UIFigure.Name=['Import ROIs to ' mainApp.Data.classiObj.strid];
            app.Data.convert={};
            app.Data.preserve={};
            app.Data.channelsToImport={};
            app.Data.channelsAvailable={};
            app.Data.targetChannel={} ; %cell(1,numel(mainApp.Data.classiObj.channelName));
            app.Data.target={};

            %if isvarname()
           % tmp=evalin('base','classitmpi')
         
           evalin( 'base', 'clear classitmp' )

             if numel(option) % 3rd argument provides a filter to narrow down the selection
                s= gatherVariablesFromWorkspace(option); % provide the name of the classfier to import from 
             else
                s= gatherVariablesFromWorkspace;
             end


            app.Data.st=s;

            displayROIs(app,option);


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            evalin( 'base', 'clear classitmp' );
            uiresume(app.Data.mainApp.ClassifierUIFigure)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume(app.Data.mainApp.ClassifierUIFigure)
            delete(app)

        end

        % Value changed function: ChoosePositionClassiferDropDown
        function ChoosePositionClassiferDropDownValueChanged(app, event)
%             value = app.ChoosePositionClassiferDropDown.Value;
%             pix=contains(app.Data.displaystr,value);
%             va=app.Data.varstr{pix};
%             tmp=evalin('base',va);
%             app.TypeROIindicestoimportEditField.Value=['1:' num2str(numel(tmp.roi))];
% 
%             if isa(tmp,'classi')
%                 app.Preserveannotations.Enable='on';
%             else
%                 app.Preserveannotations.Enable='off';
%             end
% 
%             app.Data.target=tmp;
%             %  app.Data.targetChannel={};
%             app.Data.targetChannel=cell(1,numel(app.Data.mainApp.Data.classiObj.channelName));
%             displayChannels(app)
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

            pix=find(cellfun(@(x) x==1,app.UITable.Data(:,1)));
            data=app.UITable.Data;
            % value = app.ChoosePositionClassiferDropDown.Value;

            classiObj=app.Data.mainApp.Data.classiObj;

            %    pix=contains(app.Data.displaystr,value);

            if numel(pix)==0
                errordlg('You must select at least one item !','Error');
            end

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                'Message','Importing ROIs...');
            d.Value=0.33;

            for i=1:numel(pix)
             
                va=app.Data.varstr{pix(i)};

                tmp=evalin('base',va);

                %rois=str2num(app.TypeROIindicestoimportEditField.Value);
   
                raw = data{pix(i),4};

if isnumeric(raw)
    rois = raw;
else
    roistr = strtrim(char(raw));
    if isempty(roistr)
        rois = [];
    else
        roistr = regexprep(roistr, '[,;]', ' ');
        roistr = regexprep(roistr, '\s+', ' ');
        rois = str2num(roistr); %#ok<ST2NM>
        if isempty(rois) && ~isempty(roistr)
            errordlg(sprintf('Impossible d''interpréter "%s" comme indices de ROIs.', roistr), ...
                     'Erreur ROIs');
            return;
        end
    end
end


                if numel(rois)>0
                     if  numel(find(rois)==0)~=0
                    d.Message=[ 'Importing ROIs.... From Position/Classifier: '   ' - ' num2str(i) ' / ' num2str(numel(pix))];
                    d.Value=0.33+0.66*(i-1)./numel(pix);
         
              %      aa=app.Data.channelsToImport{pix(i)}

                  tmp1=app.Data.convert{pix(i)};
            %  tmp2=app.Data.channelsToImport{pix(i)}

                   arg={'rois',rois,'convert',app.Data.convert{pix(i)},'adjustChannel',app.Data.channelsToImport{pix(i)}};
                    %   rois

                    if numel(classiObj.channelName)
                    adjustChannelName=app.Data.targetChannel{pix(i)};
                   
                    arg{end+1}='adjustName';
                    arg{end+1}=adjustChannelName;

               %     if  numel(find(rois)==0)~=0
                    %    classiObj.addROI(tmp,'rois',rois,'convert',app.Data.convert{pix(i)},'adjustName',adjustChannelName)
                    else
                    %    classiObj.addROI(tmp,'rois',rois,'convert',app.Data.convert{pix(i)})
                    end

                   % return;

disp('---- Proceed DEBUG ----');
disp(['Source var : ' va]);
disp('ROIs demandées : '), disp(rois);
disp('channelsToImport = '), disp(app.Data.channelsToImport{pix(i)});


                      classiObj.addROI(tmp,arg{:})

                    end
                end
            end

             classiObj.trainingset=intersect(classiObj.trainingset,1:numel(classiObj.roi));

            d.Value=0.99;
           % d.Message='Transfer is complete!';
          %  pause(1);
            close(d);
            uialert(app.UIFigure,'Importing ROI is complete!','Success','Icon','success');
            uiresume(app.Data.mainApp.ClassifierUIFigure)
            delete(app)

        end

        % Value changed function: Preserveannotations
        function PreserveannotationsValueChanged(app, event)


           value = app.Preserveannotations.Value;


            selection = app.UITable.Selection;

            if numel(selection)==0
                warndlg('Please select an item in table above first !')
                return;
            end

            pix=selection(1,1);

            if value==1
                app.Data.preserve{pix}=true;
            else
                app.Data.preserve{pix}=false;
            end

             displayAnnotations(app);


        end

        % Value changed function: sourceChannelList
        function sourceChannelListValueChanged(app, event)
            value = app.sourceChannelList.Value;
            displayChannels(app)
        end

        % Value changed function: sourceChannelList_2
        function sourceChannelList_2ValueChanged(app, event)
            value = app.sourceChannelList_2.Value;

            selection = app.UITable.Selection;
            pixtable=selection(1,1);

            valueSource = app.sourceChannelList.Value;
            itemSource=app.sourceChannelList.Items;

            pix=find(matches(itemSource,valueSource));

            app.Data.targetChannel{pixtable}{pix}=value;

        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)


            n=size(app.UITable.Data,1);
            t=cell(1,n);
            t(:)={true};

            app.UITable.Data(:,1)=t';


        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            n=size(app.UITable.Data,1);
            t=cell(1,n);
            t(:)={false};

            app.UITable.Data(:,1)=t';

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if indices(1,2)==4 % roi edit 
                    pix=indices(1,1);
                    app.UITable.Data{pix,1}=true;
            end

            app.UITable.Selection=indices;
           UITableSelectionChanged(app, event)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
          %  selection = app.UITable.Selection;
           % pix=selection(1,1);
            
         %   va=app.Data.varstr{pix};
          %  tmp=evalin('base',va);
         %   app.TypeROIindicestoimportEditField.Value=['1:' num2str(numel(tmp.roi))];


            %app.Data.target{pix}=tmp;
            %  app.Data.targetChannel={};
           % app.Data.targetChannel=cell(1,numel(app.Data.mainApp.Data.classiObj.channelName));

          %  PreserveannotationsValueChanged(app, event)
            displayAnnotations(app)
         displayChannelsImport(app)
            displayChannels(app)
        end

        % Value changed function: ClassesinthedestinationROIEditField
        function ClassesinthedestinationROIEditFieldValueChanged(app, event)
            value = app.ClassesinthedestinationROIEditField.Value;
            value2=app.ClassesinthesourceROIEditField.Value;
            selection = app.UITable.Selection;
            pix=selection(1,1);
           app.Data.convert{pix}={value2,value};
        end

        % Value changed function: ChannelListBox
        function ChannelListBoxValueChanged(app, event)

   % Liste actuelle des valeurs sélectionnées dans la listbox
    curr = event.Value;
    prev = event.PreviousValue;

    % Normaliser en cellstr
    if ischar(curr),   curr = {curr};   end
    if isstring(curr), curr = cellstr(curr); end
    if ischar(prev),   prev = {prev};   end
    if isstring(prev), prev = cellstr(prev); end
    if isempty(prev),  prev = {};       end

    % Quelle ligne de la table est active ?
    selection = app.UITable.Selection;
    if isempty(selection)
        return;
    end
    pixtable = selection(1,1);

    % Récupérer l'état courant stocké
    channels = app.Data.channelsToImport{pixtable};
    if ~iscell(channels)
        channels = {channels};
    end

    % Items qui ont changé d'état (cliqués/décliqués)
    clicked = setxor(curr, prev, 'stable');

    % Pour chaque item cliqué, on toggle dans channelsToImport
    for k = 1:numel(clicked)
        c = clicked{k};
        if any(strcmp(channels, c))
            % déjà présent -> on enlève
            channels = setdiff(channels, {c}, 'stable');
        else
            % pas présent -> on ajoute
            channels = [channels {c}];
        end
    end

    % Mettre à jour la valeur stockée
    app.Data.channelsToImport{pixtable} = channels;

    % Refléter dans la listbox (garantit la cohérence)
    app.ChannelListBox.Value = channels;

        end

        % Button pushed function: ImportROIsfromdiskButton
        function ImportROIsfromdiskButtonPushed(app, event)

    [file, path] = uigetfile( ...
        {'im_*.h5;im_*.mat','ROI files (HDF5 or legacy MAT)' ; ...
         'im_*.h5','HDF5 ROI' ; ...
         'im_*.mat','Legacy ROI (.mat)'}, ...
        'Select ROI files','Multiselect','on');
    if isequal(file,0), return; end
    if ischar(file), file = {file}; end

    classitmp = classi(path,'classitmp',1);
    if ~isprop(classitmp,'roi') || isempty(classitmp.roi) || ~isa(classitmp.roi,'roi')
        classitmp.roi = roi.empty(0,1);
    end

    d = uiprogressdlg(app.UIFigure,'Title','Please Wait...','Message','Importing ROIs...','Indeterminate','off');
    cleanup = onCleanup(@() (ishandle(d) && close(d)));

    n = numel(file);
    for i=1:n
        d.Value = i/n;
        d.Message = sprintf('Loading ROI %d/%d',i,n);

        f = fullfile(path,file{i});
        [~,~,ext] = fileparts(f);
        try
            switch lower(ext)
                case '.h5'
                    r = roi.fromH5(f,'headerOnly',true); % 👈 léger, pas de pixels
                case '.mat'
                    S = load(f,'roiobj');
                    if ~isfield(S,'roiobj') || ~isa(S.roiobj,'roi')
                        warning('Invalid legacy ROI: %s', file{i}); continue;
                    end
                    r = S.roiobj;
                    if isempty(r.path), r.path = path; end
                otherwise
                    warning('Unsupported ROI file: %s', file{i}); continue;
            end
            % Normalisation minimale
            if isempty(r.path), r.path = path; end
            classitmp.roi(end+1,1) = r;
        catch ME
            warning('Failed to import %s: %s', file{i}, ME.message);
        end
    end

    % TODO: attacher classitmp dans ta logique UI existante (liste, arbre, etc.)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChoosePositionClassiferDropDownLabel = uilabel(app.UIFigure);
            app.ChoosePositionClassiferDropDownLabel.Text = 'Choose Position/Classifer:';
            app.ChoosePositionClassiferDropDown = uidropdown(app.UIFigure);
            app.ChoosePositionClassiferDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePositionClassiferDropDownValueChanged, true);
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TypeROIindicestoimportEditFieldLabel = uilabel(app.UIFigure);
            app.TypeROIindicestoimportEditFieldLabel.Text = 'Type ROI indices to import:';
            app.TypeROIindicestoimportEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select'; 'Project'; 'Classifier or Position (FOV)'; 'Select ROIs'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.SelectallButton = uibutton(app.UIFigure, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.UIFigure, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.ImportedROIschannelnamemappingPanel = uipanel(app.UIFigure);
            app.ImportedROIschannelnamemappingPanel.Title = 'Imported ROIs channel name mapping';
            app.ChannelsavailableinROIstobeimportedLabel = uilabel(app.ImportedROIschannelnamemappingPanel);
            app.ChannelsavailableinROIstobeimportedLabel.Text = 'Channel names of Imported ROIs ';
            app.sourceChannelList_2 = uilistbox(app.ImportedROIschannelnamemappingPanel);
            app.sourceChannelList_2.Items = {};
            app.sourceChannelList_2.ValueChangedFcn = createCallbackFcn(app, @sourceChannelList_2ValueChanged, true);
            app.sourceChannelList_2.Tooltip = {'WARNING : Channel name will be changed upon import !!!'};
            app.sourceChannelList_2.Value = {};
            app.Label = uilabel(app.ImportedROIschannelnamemappingPanel);
            app.Label.Tooltip = {' '};
            app.Label.Text = '>>>>';
            app.WARNNGLabel = uilabel(app.ImportedROIschannelnamemappingPanel);
            app.WARNNGLabel.Text = 'For each channel of the classifier, select the corresponding channel of the imported ROI!!';
            app.ClickselectchannelsusedasinputoftheclassifierLabel = uilabel(app.ImportedROIschannelnamemappingPanel);
            app.ClickselectchannelsusedasinputoftheclassifierLabel.Text = 'Classifier channel names:';
            app.sourceChannelList = uilistbox(app.ImportedROIschannelnamemappingPanel);
            app.sourceChannelList.Items = {};
            app.sourceChannelList.ValueChangedFcn = createCallbackFcn(app, @sourceChannelListValueChanged, true);
            app.sourceChannelList.Value = {};
            app.ImportedROIsclassesmappingPanel = uipanel(app.UIFigure);
            app.ImportedROIsclassesmappingPanel.Title = 'Imported ROIs classes mapping';
            app.Preserveannotations = uicheckbox(app.ImportedROIsclassesmappingPanel);
            app.Preserveannotations.ValueChangedFcn = createCallbackFcn(app, @PreserveannotationsValueChanged, true);
            app.Preserveannotations.Text = 'Preserve annotations from the original ROI if available';
            app.ClassesinthedestinationROIEditFieldLabel = uilabel(app.ImportedROIsclassesmappingPanel);
            app.ClassesinthedestinationROIEditFieldLabel.Enable = 'off';
            app.ClassesinthedestinationROIEditFieldLabel.Text = 'Classes in the destination ROI:';
            app.ClassesinthedestinationROIEditField = uieditfield(app.ImportedROIsclassesmappingPanel, 'text');
            app.ClassesinthedestinationROIEditField.ValueChangedFcn = createCallbackFcn(app, @ClassesinthedestinationROIEditFieldValueChanged, true);
            app.ClassesinthedestinationROIEditField.Enable = 'off';
            app.ClassesinthedestinationROIEditField.Tooltip = {'Enter space-separated classes names; Type "0"  if you do not want to use a corresponding class; thenumber of items in both text fields must be  identical'; ''};
            app.ClassesinthesourceROIEditFieldLabel = uilabel(app.ImportedROIsclassesmappingPanel);
            app.ClassesinthesourceROIEditFieldLabel.Enable = 'off';
            app.ClassesinthesourceROIEditFieldLabel.Text = 'Classes in the source ROI: ';
            app.ClassesinthesourceROIEditField = uieditfield(app.ImportedROIsclassesmappingPanel, 'text');
            app.ClassesinthesourceROIEditField.Editable = 'off';
            app.SelectROIstoimportLabel = uilabel(app.UIFigure);
            app.SelectROIstoimportLabel.Text = 'Select ROIs to import:';
            app.ImportedROischannelselectionPanel = uipanel(app.UIFigure);
            app.ImportedROischannelselectionPanel.Title = 'Imported ROis channel selection';
            app.ChannelListBox = uilistbox(app.ImportedROischannelselectionPanel);
            app.ChannelListBox.Items = {};
            app.ChannelListBox.Multiselect = 'on';
            app.ChannelListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListBoxValueChanged, true);
            app.ChannelListBox.Value = {};
            app.ImportROIsfromdiskButton = uibutton(app.UIFigure, 'push');
            app.ImportROIsfromdiskButton.ButtonPushedFcn = createCallbackFcn(app, @ImportROIsfromdiskButtonPushed, true);
            app.ImportROIsfromdiskButton.Tooltip = {'Select ROI files directly in the hard drive...'};
            app.ImportROIsfromdiskButton.Text = 'Import ROIs from disk...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = roiImporterGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end