classdef ROIAdjustGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FramesrangeLabel       matlab.ui.control.Label
        FramesSlider           matlab.ui.control.RangeSlider
        FramesSliderLabel      matlab.ui.control.Label
        WarningLabel           matlab.ui.control.Label
        BinningDropDown        matlab.ui.control.DropDown
        BinningDropDownLabel   matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
        ChannelMappingTable_2  matlab.ui.control.Table
        CancelButton           matlab.ui.control.Button
        ProceedButton          matlab.ui.control.Button
        heightEditField        matlab.ui.control.NumericEditField
        heightEditFieldLabel   matlab.ui.control.Label
        widthEditField         matlab.ui.control.NumericEditField
        widthEditFieldLabel    matlab.ui.control.Label
        minYEditField          matlab.ui.control.NumericEditField
        minYEditFieldLabel     matlab.ui.control.Label
        minXEditField          matlab.ui.control.NumericEditField
        minXEditFieldLabel     matlab.ui.control.Label
        ChannelMappingTable    matlab.ui.control.Table
        UITable                matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data struct = struct()      % structure style roiImporterGUI.Data
        ChannelKeep   cell
        DataKeep      cell
        Poly                     % handle du drawpolygon
        IsUpdatingFields logical = false  % pour éviter les boucles callback
    Rect                    % handle du drawrectangle
    ROISettings   cell      % par ligne de UITable : struct('bbox',[x y w h], 'binning',factor)
    SizeMismatch  logical   % vecteur logique par ligne (ROIs de tailles différentes ?)
    LastRowSelection double = NaN;   % pour sauver les réglages de la ligne précédente
    end
    
    methods (Access = private)
        
       
    function localCloseProgress(app, dlg)
        try
            if ~isempty(dlg) && isvalid(dlg)
                close(dlg);
            end
        catch
        end
    end


           function tf = hasValidROIs(app,obj)
            tf = false;
            if ~isprop(obj,'roi') || isempty(obj.roi)
                return;
            end
            r = obj.roi(1);
            if ~isa(r,'roi') || isempty(r.id)
                return;
            end
            tf = true;
           end

         function displayROIs(app, option)

            s         = app.Data.st;
            mainApp   = app.Data.mainApp;
            cc        = 1;
            displaystr2 = {};
            varstr      = {};
            target      = {};

            % --- Parcours des projets (shallow) ---
            for i = 1:numel(s.Project)
                proj = s.Project{i};
                shallowObj = evalin('base', proj); %#ok<NASGU>

                % FOV
                if ~isempty(s.Projectpos)
                    for k = 1:numel(s.Projectpos{i})
                        tmp = evalin('base', sprintf('%s.fov(%d)', proj, k));
                        if hasValidROIs(app,tmp)
                            varstr{cc}      = sprintf('%s.fov(%d)', proj, k);
                            displaystr2{cc,1} = proj;
                            displaystr2{cc,2} = s.Projectpos{i}{k};
                            displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                            target{cc}      = tmp;
                            % après avoir mis target{cc} = tmp;
rois = tmp.roi(:);
w = arrayfun(@(r) r.value(3), rois);
h = arrayfun(@(r) r.value(4), rois);
app.SizeMismatch(cc,1) = (numel(unique(w)) > 1 || numel(unique(h)) > 1);

                            cc = cc + 1;
                        end
                    end
                end

                % Classifications rattachées au projet
                if ~isempty(s.Projectclassi)
                    for k = 1:numel(s.Projectclassi{i})
                        val = k;
                        if nargin == 3 && ~isempty(option)
                            mp = evalin('base', proj);
                            for l = 1:numel(mp.processing.classification)
                                if strcmp(mp.processing.classification(l).strid, option)
                                    val = l;
                                    break
                                end
                            end
                        end
                        tmp = evalin('base', sprintf('%s.processing.classification(%d)', proj, val));
                        if hasValidROIs(app,tmp)
                            varstr{cc}      = sprintf('%s.processing.classification(%d)', proj, val);
                            displaystr2{cc,1} = proj;
                            displaystr2{cc,2} = s.Projectclassi{i}{k};
                            displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                            target{cc}      = tmp;
                            % après avoir mis target{cc} = tmp;
rois = tmp.roi(:);
w = arrayfun(@(r) r.value(3), rois);
h = arrayfun(@(r) r.value(4), rois);
app.SizeMismatch(cc,1) = (numel(unique(w)) > 1 || numel(unique(h)) > 1);
                            cc = cc + 1;
                        end
                    end
                end
            end

            % --- Parcours des classi standalone (comme roiImporterGUI) ---
            for i = 1:numel(s.Classifier)
                clas = s.Classifier{i};
                if isempty(clas)
                    continue;
                end
                if isstring(clas)
                    clas = clas(1);
                    clas = char(clas);
                end
                if ~ischar(clas)
                    warning('ROIAdjustGUI:ClassifierType',...
                        'Entrée st.Classifier{%d} non textuelle, ignorée.', i);
                    continue;
                end

                try
                    tmp = evalin('base', clas);
                catch
                    warning('ROIAdjustGUI:EvalinFailed',...
                        'Impossible de trouver la variable "%s" dans le workspace.', clas);
                    continue;
                end

                if hasValidROIs(app,tmp)
                    varstr{cc}      = clas;
                    displaystr2{cc,1} = '';
                    displaystr2{cc,2} = clas;
                    displaystr2{cc,3} = sprintf('1:%d', numel(tmp.roi));
                    target{cc}      = tmp;
                    % après avoir mis target{cc} = tmp;
rois = tmp.roi(:);
w = arrayfun(@(r) r.value(3), rois);
h = arrayfun(@(r) r.value(4), rois);
app.SizeMismatch(cc,1) = (numel(unique(w)) > 1 || numel(unique(h)) > 1);
                    cc = cc + 1;
                end
            end

            app.Data.varstr      = varstr;
            app.Data.displaystr2 = displaystr2;
            app.Data.target      = target;

           nRows = size(displaystr2,1);

t = app.UITable;
t.Data = [];

t.ColumnWidth    = {50, 250, 200, 'auto'};
t.ColumnEditable = [true false false true];

d = cell(nRows,4);
for i = 1:nRows
    d{i,1} = false;

    % Optionnel : pré-sélectionner la première si option fourni
    if exist('option','var') && ~isempty(option) && i==1
        d{i,1} = true;
    end

    d{i,2} = displaystr2{i,1};
    d{i,3} = displaystr2{i,2};
    d{i,4} = displaystr2{i,3};
end

t.Data = d;


app.Data.target      = target;
app.Data.varstr      = varstr;
app.Data.displaystr2 = displaystr2;

% init des settings par ligne
nRows = numel(target);
app.ROISettings = cell(nRows,1);
if isempty(app.SizeMismatch)
    app.SizeMismatch = false(nRows,1);
end
        

            % Si on a au moins une ligne, on la sélectionne par défaut
            if nRows > 0
                app.UITable.Selection = [1 1];
                UITableSelectionChanged(app, struct());
            end
         end
          function saveTargetContainerIfNeeded(app, tgt, row)
            if ~isa(tgt, 'classi')
                return;
            end

            expr = '';
            try
                if isfield(app.Data, 'varstr') && row <= numel(app.Data.varstr)
                    expr = app.Data.varstr{row};
                end
            catch
                expr = '';
            end

            try
                fprintf('[ROIAdjust] saving classifier container for row=%d expr=%s\n', row, expr);
                classiSave(tgt);
            catch ME
                warning('ROIAdjustGUI:ClassiSaveFailed', ...
                    'Row %d classifier %s: classifier object save failed (%s). ROI files were still saved.', ...
                    row, expr, ME.message);
            end
          end

         function updateWarningLabel(app, row)
    if isempty(app.SizeMismatch) || row > numel(app.SizeMismatch)
        app.WarningLabel.Text = '';
        return;
    end

    if app.SizeMismatch(row)
        app.WarningLabel.Text = 'Warning: ROIs for this item have different sizes; batch adjustment will be harmonized.';
    else
        app.WarningLabel.Text = '';
    end
end

function saveCurrentRowSettings(app)
    if isnan(app.LastRowSelection)
        return;
    end
    row = app.LastRowSelection;
    if row < 1 || row > numel(app.ROISettings)
        return;
    end

    % Binning actuel
    bin = str2double(app.BinningDropDown.Value);
    if isnan(bin) || bin <= 0
        bin = 1;
    end

    % bbox full-res
    x = round(app.minXEditField.Value);
    y = round(app.minYEditField.Value);
    w = round(app.widthEditField.Value);
    h = round(app.heightEditField.Value);

    % Frames range (slider)
    fr = [];
    try
        fr = round(double(app.FramesSlider.Value(:)'));
    catch
        fr = [];
    end
    if numel(fr) ~= 2
        fr = [1 inf];
    end
    fr = [min(fr) max(fr)];


    % merge avec existant si présent
    st = app.ROISettings{row};
    if ~isempty(st) && isstruct(st)
        st.bbox = [x y w h];
        st.binning = bin;
        st.framesRange = fr;
        app.ROISettings{row} = st;
    else
        app.ROISettings{row} = struct('bbox',[x y w h], 'binning',bin, 'framesRange',fr);
    end
end

function syncFramesSliderFromRow(app, row)
    % fallback safe defaults
    f1 = 1; f2 = 1;

    % déterminer Tmax de la ligne (best effort)
    Tmax = 1;
    try
        tgt = app.Data.target{row};
        rois = tgt.roi(:);
        if ~isempty(rois)
            r = rois(1);
            if isempty(r.image)
                r.load();
            end
            sz = size(r.image);
            if numel(sz) >= 4 && sz(4) > 0
                Tmax = sz(4);
            end
        end
    catch
        Tmax = 1;
    end

    % range mémorisé ?
    st = [];
    if ~isempty(app.ROISettings) && row <= numel(app.ROISettings)
        st = app.ROISettings{row};
    end

    if ~isempty(st) && isstruct(st) && isfield(st,'framesRange') && ~isempty(st.framesRange)
        fr = double(st.framesRange(:)');
        if numel(fr) == 2
            f1 = min(fr); f2 = max(fr);
        end
    else
        % défaut: tout
        f1 = 1; f2 = Tmax;
    end

    % clamp
    f1 = max(1, min(round(f1), Tmax));
    f2 = max(1, min(round(f2), Tmax));
    if f2 < f1, f2 = f1; end

    % appliquer limites + valeur
    try
        app.FramesSlider.Limits = [1 Tmax];
    catch
        % si ta version ne supporte pas, on ignore
    end
    try
        app.FramesSlider.Value = [f1 f2];
    catch
    end

    % label
    if isprop(app,'FramesrangeLabel') && ~isempty(app.FramesrangeLabel) && isvalid(app.FramesrangeLabel)
        app.FramesrangeLabel.Text = sprintf('Frames range: %d-%d / %d', f1, f2, Tmax);
    end
end


        %--------------------------------------------------------------
        % Mise à jour des tables Channels & Dataseries pour la ligne sélectionnée
        %--------------------------------------------------------------

        function updateChannelAndDataTables(app)
    sel = app.UITable.Selection;
    if isempty(sel)
        app.ChannelMappingTable.Data   = {};
        app.ChannelMappingTable_2.Data = {};
        return;
    end
    row = sel(1,1);

    tgt  = app.Data.target{row};
    if isempty(tgt), return; end
    rois = tgt.roi(:);

    if isempty(rois)
        app.ChannelMappingTable.Data   = {};
        app.ChannelMappingTable_2.Data = {};
        return;
    end

 % -----------------------
% 🔹 CHANNELS
% -----------------------
allChannels = {};
for r = 1:numel(rois)
    ch = rois(r).display.channel;
    if ischar(ch), ch = {ch}; end
    allChannels = [allChannels, ch]; %#ok<AGROW>
end
chNames = unique(allChannels,'stable');
nCh = numel(chNames);

oldCh = [];
if numel(app.ChannelKeep) >= row && ~isempty(app.ChannelKeep{row})
    oldCh = app.ChannelKeep{row}; % struct array keep/src/dst
end

dataCh = cell(nCh,3);
for i = 1:nCh
    src = chNames{i};
    keep = true;
    dst  = src; % défaut

    if ~isempty(oldCh)
        idx = find(strcmp({oldCh.src}, src), 1);
        if ~isempty(idx)
            keep = oldCh(idx).keep;
            if isfield(oldCh,'dst') && ~isempty(oldCh(idx).dst)
                dst = oldCh(idx).dst;
            end
        end
    end

    dataCh{i,1} = keep;
    dataCh{i,2} = src;
    dataCh{i,3} = dst;
end

app.ChannelMappingTable.Data = dataCh;
app.ChannelMappingTable.ColumnEditable = [true false true];


% -----------------------
% 🔹 DATASERIES
% -----------------------
allDS = {};
for r = 1:numel(rois)
    d = rois(r).data;
    if isempty(d), continue; end
    for k = 1:numel(d)
        if isprop(d(k),'groupid') && ~isempty(d(k).groupid)
            allDS{end+1} = char(d(k).groupid); %#ok<AGROW>
        end
    end
end
dsNames = unique(allDS,'stable');
nDS = numel(dsNames);

oldDS = [];
if numel(app.DataKeep) >= row && ~isempty(app.DataKeep{row})
    oldDS = app.DataKeep{row}; % struct array keep/src/dst
end

dataDS = cell(nDS,3);
for i = 1:nDS
    src = dsNames{i};
    keep = true;
    dst  = src; % défaut

    if ~isempty(oldDS)
        idx = find(strcmp({oldDS.src}, src), 1);
        if ~isempty(idx)
            keep = oldDS(idx).keep;
            if isfield(oldDS,'dst') && ~isempty(oldDS(idx).dst)
                dst = oldDS(idx).dst;
            end
        end
    end

    dataDS{i,1} = keep;
    dataDS{i,2} = src;
    dataDS{i,3} = dst;
end

app.ChannelMappingTable_2.Data = dataDS;
app.ChannelMappingTable_2.ColumnEditable = [true false true];

        end

        %--------------------------------------------------------------
        % Aperçu ROI et polygon
        %--------------------------------------------------------------
    function updatePolygonPreview(app)
    sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);

    if row > numel(app.Data.target) || isempty(app.Data.target{row})
        return;
    end

    tgt  = app.Data.target{row};
    rois = tgt.roi(:);
    if isempty(rois), return; end

    % ROIs sélectionnées dans la colonne 4
    raw = app.UITable.Data{row,4};
    roisIdx = parseROIString(app, raw, numel(rois));
    if isempty(roisIdx)
        roisIdx = 1;
    end
    refIdx = roisIdx(1);
    r = rois(refIdx);

    % image full-res de référence
    imFull = getROIFramePreview(app, r);
    [hFull, wFull, ~] = size(imFull);

    % récupère / initialise settings pour cette ligne
    st = app.ROISettings{row};
    if isempty(st) || ~isfield(st,'bbox') || isempty(st.bbox)
        bboxFull = [1 1 wFull hFull];
        bin = 1;
    else
        bboxFull = st.bbox;
        if isfield(st,'binning') && ~isempty(st.binning)
            bin = st.binning;
        else
            bin = 1;
        end
    end

    % s'assure que les valeurs sont entières
    bboxFull = round(bboxFull);
    bin = max(1, round(bin));

    % synchonise dropdown binning
    if any(strcmp(app.BinningDropDown.Items, num2str(bin)))
        app.BinningDropDown.Value = num2str(bin);
    else
        app.BinningDropDown.Value = app.BinningDropDown.Items{1};
        bin = str2double(app.BinningDropDown.Value);
    end

    % applique le binning pour l'affichage
    imDisp = applyBinning(app, imFull, bin);

    cla(app.UIAxes);
    imagesc(app.UIAxes, imDisp);
    colormap(app.UIAxes, gray);
    axis(app.UIAxes,'image');
    app.UIAxes.YDir = 'reverse';
    hold(app.UIAxes,'on');
    title(app.UIAxes, sprintf('ROI preview (idx %d)', refIdx));

    % bbox full-res -> coords sur image binned
    xF = bboxFull(1);  yF = bboxFull(2);
    wF = bboxFull(3);  hF = bboxFull(4);

    xD = xF / bin;
    yD = yF / bin;
    wD = wF / bin;
    hD = hF / bin;

    rectPosDisp = [xD yD wD hD];

    % supprime ancien rectangle
    if ~isempty(app.Rect) && isvalid(app.Rect)
        delete(app.Rect);
    end

    app.Rect = drawrectangle(app.UIAxes, ...
        'Position', rectPosDisp, ...
        'FaceAlpha', 0.1);

    % listeners : quand on bouge le rectangle, on met à jour les champs
    addlistener(app.Rect,'MovingROI',@(src,evt) app.syncFieldsFromRect());
    addlistener(app.Rect,'ROIMoved', @(src,evt) app.syncFieldsFromRect());

    % met à jour les champs (en full-res)
    app.minXEditField.Value   = xF;
    app.minYEditField.Value   = yF;
    app.widthEditField.Value  = wF;
    app.heightEditField.Value = hF;

    % mémorise
    mergeROISettings(app, row, struct('bbox',[xF yF wF hF], 'binning',bin));

end

function mergeROISettings(app, row, patch)
    if row < 1 || row > numel(app.ROISettings), return; end
    st = app.ROISettings{row};
    if isempty(st) || ~isstruct(st)
        st = struct();
    end
    fn = fieldnames(patch);
    for k = 1:numel(fn)
        st.(fn{k}) = patch.(fn{k});
    end
    app.ROISettings{row} = st;
end


        function syncFieldsFromPolygon(app)
            if isempty(app.Poly) || ~isvalid(app.Poly)
                return;
            end
            pos = app.Poly.Position;
            minX = min(pos(:,1));
            maxX = max(pos(:,1));
            minY = min(pos(:,2));
            maxY = max(pos(:,2));
            w = maxX - minX;
            h = maxY - minY;

            app.IsUpdatingFields = true;
            app.minXEditField.Value   = minX;
            app.minYEditField.Value   = minY;
            app.widthEditField.Value  = w;
            app.heightEditField.Value = h;
            app.IsUpdatingFields      = false;
        end

        function syncFieldsFromRect(app)
    if isempty(app.Rect) || ~isvalid(app.Rect)
        return;
    end

    sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);

    % bin pour cette ligne
    st = app.ROISettings{row};
    if isempty(st) || ~isfield(st,'binning') || isempty(st.binning)
        bin = str2double(app.BinningDropDown.Value);
        if isnan(bin) || bin <= 0, bin = 1; end
    else
        bin = st.binning;
    end

    posDisp = app.Rect.Position;  % [xD yD wD hD] en coords binned
    xF = round(posDisp(1) * bin);
    yF = round(posDisp(2) * bin);
    wF = round(posDisp(3) * bin);
    hF = round(posDisp(4) * bin);

    % maj des champs (entiers)
    app.minXEditField.Value   = xF;
    app.minYEditField.Value   = yF;
    app.widthEditField.Value  = wF;
    app.heightEditField.Value = hF;

    % maj settings
   mergeROISettings(app, row, struct('bbox',[xF yF wF hF], 'binning',bin));

end

function syncRectFromFields(app)
    sel = app.UITable.Selection;
    if isempty(sel) || isempty(app.Rect) || ~isvalid(app.Rect)
        return;
    end
    row = sel(1,1);

    xF = round(app.minXEditField.Value);
    yF = round(app.minYEditField.Value);
    wF = round(app.widthEditField.Value);
    hF = round(app.heightEditField.Value);

    st = app.ROISettings{row};
    if isempty(st) || ~isfield(st,'binning') || isempty(st.binning)
        bin = str2double(app.BinningDropDown.Value);
        if isnan(bin) || bin <= 0, bin = 1; end
    else
        bin = st.binning;
    end

    xD = xF / bin;
    yD = yF / bin;
    wD = wF / bin;
    hD = hF / bin;

    app.Rect.Position = [xD yD wD hD];

    mergeROISettings(app, row, struct('bbox',[xF yF wF hF], 'binning',bin));

end


        function syncPolygonFromFields(app, whichField)
            if app.IsUpdatingFields, return; end
            if isempty(app.Poly) || ~isvalid(app.Poly)
                return;
            end

            x  = app.minXEditField.Value;
            y  = app.minYEditField.Value;
            w  = max(1, app.widthEditField.Value);
            h  = max(1, app.heightEditField.Value);

            % Si on modifie width/height, on garde le centre de la forme
            if any(strcmp(whichField, {'width','height'}))
                pos = app.Poly.Position;
                minX = min(pos(:,1)); maxX = max(pos(:,1));
                minY = min(pos(:,2)); maxY = max(pos(:,2));
                cx = (minX + maxX)/2;
                cy = (minY + maxY)/2;
                x  = cx - w/2;
                y  = cy - h/2;
                app.minXEditField.Value = x;
                app.minYEditField.Value = y;
            end

            polyPos = [x y; x+w y; x+w y+h; x y+h];

            app.IsUpdatingFields = true;
            app.Poly.Position = polyPos;
            app.IsUpdatingFields = false;
        end

        function roisIdx = parseROIString(app,str, nMax)
            % interprète le contenu de la colonne "Select ROIs"
            if isnumeric(str)
                roisIdx = str;
                roisIdx = roisIdx(roisIdx>=1 & roisIdx<=nMax);
                return;
            end
            s = strtrim(char(str));
            if isempty(s)
                roisIdx = 1:nMax;
                return;
            end
            s = regexprep(s,'[,;]',' ');
            s = regexprep(s,'\s+',' ');
            try
                roisIdx = str2num(s); %#ok<ST2NM>
            catch
                roisIdx = [];
            end
            roisIdx = unique(roisIdx);
            roisIdx = roisIdx(roisIdx>=1 & roisIdx<=nMax);
        end


        function im = getROIFramePreview(~, r)
    % Assure que l'image de la ROI est chargée
    if isempty(r.image)
        try
            r.load();      % ta méthode existante, sans argument
        catch ME
            warning('ROIAdjustGUI:getROIFramePreview',...
                'Impossible de charger l''image pour ROI "%s": %s', r.id, ME.message);
        end
    end

    if ~isempty(r.image)
        % r.image : H x W x C x T
        im = r.image(:,:,1,1);      % channel 1, frame 1
    else
        % fallback si vraiment rien
        im = zeros(64,64,'uint8');
    end

    % On s'assure d’avoir 2D pour imagesc
    if ndims(im) > 2
        im = im(:,:,1);
    end
        end


function imDisp = applyBinning(~, imFull, bin)
    if bin <= 1
        imDisp = imFull;
    else
        imDisp = imresize(imFull, 1/bin);
    end
end

function proceed(app)

    dataTable = app.UITable.Data;
    if isempty(dataTable)
        uialert(app.UIFigure,'No ROI source available','Error');
        return;
    end

    selRows = find(cell2mat(dataTable(:,1)));
    if isempty(selRows)
        uialert(app.UIFigure,'No ROI source selected','Error');
        return;
    end

    % -------------------------------------------------------------
    % Save current row settings (bbox/binning) + NEW: frames range
    % -------------------------------------------------------------
    saveCurrentRowSettings(app);
    saveCurrentRowFramesRange(app);   % <-- minimal add (local helper below)

    % ---------- pré-scan ----------
    totalROI = 0;
    for idxRow = selRows'
        if idxRow > numel(app.Data.target) || isempty(app.Data.target{idxRow})
            continue;
        end
        tgt  = app.Data.target{idxRow};
        rois = tgt.roi(:);
        nROI = numel(rois);
        if nROI == 0, continue; end

        rawROI = dataTable{idxRow,4};
        roiIdx = parseROIString(app, rawROI, nROI);
        if isempty(roiIdx), roiIdx = 1:nROI; end

        totalROI = totalROI + numel(roiIdx);
    end

    if totalROI == 0
        uialert(app.UIFigure,'No ROI to process (empty selections).','Error');
        return;
    end

    % ---------- confirmation ----------
    msg = sprintf(['You are about to modify %d ROI(s) across %d selected source(s).\n\n' ...
                   'This will apply adjustROIAdvanced() and overwrite ROI geometry/channels/dataseries.\n' ...
                   'HDF5 will be rewritten with ONLY the selected frame range for each item.\n\n' ...
                   'Do you want to continue?'], totalROI, numel(selRows));

    choice = uiconfirm(app.UIFigure, msg, 'Confirm ROI modification', ...
        'Options', {'Proceed','Cancel'}, ...
        'DefaultOption', 2, ...
        'CancelOption', 2);

    if ~strcmp(choice,'Proceed')
        return;
    end

    % ---------- progressdlg ----------
    d = uiprogressdlg(app.UIFigure, ...
        'Title','Please Wait...', ...
        'Message','Adjusting ROIs...', ...
        'Indeterminate','off', ...
        'Cancelable','on');
    d.Value = 0;

    roiCount = 0;

    try
        for idxRow = selRows'

            if d.CancelRequested
                close(d);
                uialert(app.UIFigure, sprintf('Cancelled: %d/%d ROI(s) processed.', roiCount, totalROI), 'Cancelled');
                return;
            end

            if idxRow > numel(app.Data.target) || isempty(app.Data.target{idxRow})
                continue;
            end

            tgt  = app.Data.target{idxRow};
            rois = tgt.roi(:);
            nROI = numel(rois);
            if nROI == 0
                continue;
            end

            fprintf('[ROIAdjust] row=%d expr=%s nROI=%d\n', idxRow, app.Data.varstr{idxRow}, nROI);

            rawROI = dataTable{idxRow,4};
            roiIdx = parseROIString(app, rawROI, nROI);
            if isempty(roiIdx), roiIdx = 1:nROI; end

            % ---- bbox + binning (bbox = LOCAL coords in ROI patch) ----
            bboxLocal = [];
            bin  = [];

            if ~isempty(app.ROISettings) && idxRow <= numel(app.ROISettings) ...
                    && ~isempty(app.ROISettings{idxRow})
                st = app.ROISettings{idxRow};
                if isfield(st,'bbox') && ~isempty(st.bbox)
                    bboxLocal = round(st.bbox);
                end
                if isfield(st,'binning') && ~isempty(st.binning)
                    bin = st.binning;
                end
            end

            if isempty(bboxLocal)
                xL = round(app.minXEditField.Value);
                yL = round(app.minYEditField.Value);
                wL = round(app.widthEditField.Value);
                hL = round(app.heightEditField.Value);
                bboxLocal = [xL yL wL hL];
            end
            bboxLocal(3:4) = max(bboxLocal(3:4), 1);

            % Taille cible commune
            wT = bboxLocal(3);
            hT = bboxLocal(4);

            % ---- ROI modèle = première ROI sélectionnée ----
            modelIdx = roiIdx(1);
            r0  = rois(modelIdx);

            % frames range demandé pour cette ligne
            [f1,f2] = getFramesRangeForRow(app, idxRow);   % <-- NEW helper
            framesVec_row = [];                            % sera clampé par ROI plus bas

            % IMPORTANT: on a besoin des dims du modèle (w0/h0)
            b0  = r0.value(1:4);
            w0  = b0(3);
            h0  = b0(4);

            xRel0 = bboxLocal(1);
            yRel0 = bboxLocal(2);

            dCx = (xRel0 + wT/2) - (w0/2);
            dCy = (yRel0 + hT/2) - (h0/2);

            % ---- build keep + rename (channels) ----
            renameCh = containers.Map('KeyType','char','ValueType','char');
            keepChNames = {};

            if ~isempty(app.ChannelKeep) && idxRow <= numel(app.ChannelKeep) && ~isempty(app.ChannelKeep{idxRow})
                mapCh = app.ChannelKeep{idxRow};
                mapCh = mapCh(:);
                for ii = 1:numel(mapCh)
                    if mapCh(ii).keep
                        keepChNames{end+1} = mapCh(ii).src; %#ok<AGROW>
                        if ~strcmp(mapCh(ii).dst, mapCh(ii).src)
                            renameCh(mapCh(ii).src) = mapCh(ii).dst;
                        end
                    end
                end
            end
            keepChNames = unique(keepChNames,'stable');

            % ---- build keep + rename (dataseries) ----
            renameDS = containers.Map('KeyType','char','ValueType','char');
            keepDSNames = {};

            if ~isempty(app.DataKeep) && idxRow <= numel(app.DataKeep) && ~isempty(app.DataKeep{idxRow})
                mapDS = app.DataKeep{idxRow};
                mapDS = mapDS(:);
                for ii = 1:numel(mapDS)
                    if mapDS(ii).keep
                        keepDSNames{end+1} = mapDS(ii).src; %#ok<AGROW>
                        if ~strcmp(mapDS(ii).dst, mapDS(ii).src)
                            renameDS(mapDS(ii).src) = mapDS(ii).dst;
                        end
                    end
                end
            end
            keepDSNames = unique(keepDSNames,'stable');

            % ---- apply ROI par ROI ----
            for rIdx = roiIdx

                if d.CancelRequested
                    close(d);
                    uialert(app.UIFigure, sprintf('Cancelled: %d/%d ROI(s) processed.', roiCount, totalROI), 'Cancelled');
                    return;
                end

                roiCount = roiCount + 1;

                d.Value = min(roiCount / totalROI, 1);
                d.Message = sprintf('Adjusting ROI %d/%d (row %d)', roiCount, totalROI, idxRow);
                drawnow limitrate;

                rr = rois(rIdx);

                % --------- Load image if needed (we need T to clamp frames) ----------
                if isempty(rr.image)
                    try
                        rr.load();
                    catch MEload
                        warning('ROIAdjustGUI:LoadFailed',...
                            'Row %d ROI %s: failed to load image (%s). Skipping ROI.', idxRow, rr.id, MEload.message);
                        continue;
                    end
                end

                if isempty(rr.image)
                    warning('ROIAdjustGUI:NoImage',...
                        'Row %d ROI %s: image empty after load. Skipping ROI.', idxRow, rr.id);
                    continue;
                end

                T = size(rr.image,4);
                if isempty(T) || T < 1, T = 1; end

                % build framesVec for this ROI (clamped)
                f1c = max(1, min(T, round(f1)));
                f2c = max(1, min(T, round(f2)));
                if f2c < f1c
                    tmp = f1c; f1c = f2c; f2c = tmp;
                end
                framesVec_row = f1c:f2c;

                % bbox FOV actuelle (pour calculer centre local)
                bi = rr.value(1:4);
                wi = bi(3);
                hi = bi(4);

                xRel = (wi/2 + dCx) - wT/2;
                yRel = (hi/2 + dCy) - hT/2;

                xRel = max(1, round(xRel));
                yRel = max(1, round(yRel));

                bboxToApply = [xRel yRel wT hT];

                args = {'bbox', bboxToApply, 'localCrop', true};

                if ~isempty(keepChNames), args = [args, {'keepChannels', keepChNames}]; end
                if ~isempty(keepDSNames), args = [args, {'keepDataseries', keepDSNames}]; end
                if ~isempty(bin),        args = [args, {'binning', bin}]; end

                % rename metadata only (as before)
                if renameCh.Count > 0,  args = [args, {'renameChannels', renameCh}]; end
                if renameDS.Count > 0,  args = [args, {'renameDataseries', renameDS}]; end

                % ---------------- APPLY (geometry + crop only selected frames) ----------------
                before = rr.value(1:4);
                rr.adjustROIAdvanced(args{:}, 'frames', framesVec_row);  % <-- NEW
                after  = rr.value(1:4);
                fprintf('  ROI %d: [%g %g %g %g] -> [%g %g %g %g] | frames=[%d..%d]/%d\n', ...
                    rIdx, before, after, f1c, f2c, T);

                % ---------------- FULL HDF5 REWRITE with ONLY selected frames ----------------
                % rr.image now contains ONLY framesVec_row (because adjustROISize localCrop cropped frames)
                % Ensure we don't carry an old abs start that would create holes
                oldAbsStart = [];
                try
                    if isfield(rr.display,'write_abs_start') && ~isempty(rr.display.write_abs_start)
                        oldAbsStart = rr.display.write_abs_start;
                    end
                catch
                end

                try
                    if ~isfield(rr.display,'write_abs_start') || isempty(rr.display.write_abs_start)
                        % field may not exist -> create it
                        rr.display.write_abs_start = 0;
                    else
                        rr.display.write_abs_start = 0;
                    end

                    rr.save();  % FULL SAVE => new HDF5 contains ONLY current rr.image frames

                catch MEsave
                    warning('ROIAdjustGUI:SaveFailed', ...
                        'Row %d ROI %s: save failed (%s).', idxRow, rr.id, MEsave.message);
                end

                % restore previous abs start if it existed
                try
                    if ~isempty(oldAbsStart)
                        rr.display.write_abs_start = oldAbsStart;
                    else
                        % leave at 0 or clear; choose clear to avoid side effects
                        rr.display.write_abs_start = [];
                    end
                catch
                end

                % ---------------- HDF5 dataset renaming (same logic, but validated) ----------------
                if renameCh.Count > 0
                    [okRename, renameChSafe, why] = validateRenameChMap(app, renameCh, keepChNames);
                    if okRename && renameChSafe.Count > 0
                        try
                            rr.roi_rename_h5_channels(renameChSafe);
                        catch MEh5
                            warning('ROIAdjustGUI:RenameH5Failed', ...
                                'Row %d ROI %s: HDF5 rename failed (%s). Skipping rename.', ...
                                idxRow, rr.id, MEh5.message);
                        end
                    else
                        warning('ROIAdjustGUI:InvalidChannelRename', ...
                            'Row %d ROI %s: channel rename skipped (%s).', ...
                            idxRow, rr.id, char(why));
                    end
                end

            end % roi loop
            saveTargetContainerIfNeeded(app, tgt, idxRow);
        end % row loop

        close(d);
        uialert(app.UIFigure,'ROIs adjusted successfully','Success','Icon','success');
        delete(app);

    catch ME
        try, close(d); catch, end
        uialert(app.UIFigure, sprintf('Error during processing:\n%s', ME.message), 'Error');
        rethrow(ME);
    end


% ===================== LOCAL HELPERS (minimal, self-contained) =====================

function saveCurrentRowFramesRange(app)
    if isnan(app.LastRowSelection), return; end
    row = app.LastRowSelection;
    if row < 1 || row > numel(app.ROISettings), return; end

    % RangeSlider.Value is [min max]
    v = app.FramesSlider.Value;
    if isempty(v) || numel(v) ~= 2
        return;
    end
    f1 = round(min(v));
    f2 = round(max(v));
    if f1 < 1, f1 = 1; end
    if f2 < 1, f2 = 1; end

    st = app.ROISettings{row};
    if isempty(st) || ~isstruct(st)
        st = struct();
    end
    st.framesRange = [f1 f2];
    app.ROISettings{row} = st;
end

function [f1,f2] = getFramesRangeForRow(app, row)
    % default from slider
    v = app.FramesSlider.Value;
    if isempty(v) || numel(v) ~= 2
        f1 = 1; f2 = inf;
    else
        f1 = round(min(v));
        f2 = round(max(v));
        if f1 < 1, f1 = 1; end
        if f2 < 1, f2 = 1; end
    end

    % if stored per row, use it
    if ~isempty(app.ROISettings) && row <= numel(app.ROISettings) && ~isempty(app.ROISettings{row})
        st = app.ROISettings{row};
        if isstruct(st) && isfield(st,'framesRange') && ~isempty(st.framesRange) && numel(st.framesRange)==2
            f1 = round(min(st.framesRange));
            f2 = round(max(st.framesRange));
            if f1 < 1, f1 = 1; end
            if f2 < 1, f2 = 1; end
        end
    end
end

end % proceed


function [ok, renameChSafe, msg] = validateRenameChMap(app, renameCh, keepChNames)
% Validate rename map for channels (HDF5 dataset rename safety)
% - keeps only src in keepChNames
% - trims, ignores empty / identity
% - checks that destination names do not collide AFTER sanitizeDatasetName()

ok = true;
msg = "";
renameChSafe = containers.Map('KeyType','char','ValueType','char');

if isempty(renameCh) || ~isa(renameCh,'containers.Map') || renameCh.Count == 0
    return;
end

keys = renameCh.keys;
dstSanitized = {};

for i = 1:numel(keys)
    src = strtrim(char(keys{i}));
    dst = strtrim(char(renameCh(src)));

    if isempty(src) || isempty(dst) || strcmp(src,dst)
        continue;
    end

    % safety: only rename channels that are kept
    if nargin >= 3 && ~isempty(keepChNames) && ~any(strcmp(keepChNames, src))
        continue;
    end

    % sanitize check
    dstSan = sanitizeDatasetName(dst);
    if isempty(dstSan)
        ok = false;
        msg = sprintf('Destination "%s" invalid after sanitize.', dst);
        return;
    end

    renameChSafe(src) = dst;
    dstSanitized{end+1} = dstSan; %#ok<AGROW>
end

if renameChSafe.Count == 0
    return;
end

% collision after sanitization (would clash in HDF5 paths)
if numel(unique(dstSanitized)) ~= numel(dstSanitized)
    ok = false;
    msg = 'Destination names collide after sanitize (same HDF5 dataset name).';
    renameChSafe = containers.Map('KeyType','char','ValueType','char');
end
end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Démarrage standalone :
    %   ROIAdjustGUI()

    app.Data.mainApp = [];              % pas de calling app à ce stade
    app.UIFigure.Name = 'Batch ROI adjust';

    % Récupère les projets / classi / etc. dans le workspace
    s = gatherVariablesFromWorkspace;
    app.Data.st = s;

    app.Data.target      = {};  % objets (classi, shallow.fov, etc.)
    app.Data.varstr      = {};  % expressions dans base workspace
    app.Data.displaystr2 = {};  % [Project, Classifier/FOV, ROIs string]

    % Remplit la UITable avec les objets détectés
    displayROIs(app, []);

    if ~isempty(app.UITable.Data)
    app.UITable.Selection = [1 1];
    UITableSelectionChanged(app, struct());
end


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;

    % Si on édite la colonne "Select ROIs" (4), on force la case "Select" à true
    if indices(1,2) == 4
        pix = indices(1,1);
        app.UITable.Data{pix,1} = true;
    end

    % Met la sélection sur la ligne/cellule éditée
    app.UITable.Selection = indices;

    % Rejoue le pipeline : update tables + affichage ROI
    UITableSelectionChanged(app, event);
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
           % Sauvegarde des settings de la ligne précédente (si existante)
    saveCurrentRowSettings(app);

    sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);
    app.LastRowSelection = row;

    % update tables (channels/datas) si tu en as une
    updateChannelAndDataTables(app);

    % warning éventuel
    updateWarningLabel(app, row);

    % NEW: sync slider to this row
syncFramesSliderFromRow(app, row);

    % aperçu ROI
    updatePolygonPreview(app);
        end

        % Cell edit callback: ChannelMappingTable
        function ChannelMappingTableCellEdit(app, event)
        data = app.ChannelMappingTable.Data;
sel  = app.UITable.Selection;
if isempty(sel) || isempty(data), return; end
row = sel(1,1);

nCh = size(data,1);
map(1,nCh) = struct('src','', 'dst','', 'keep', true);

for i = 1:nCh
    map(i).keep = ~isempty(data{i,1}) && logical(data{i,1});
    map(i).src  = char(string(data{i,2}));
    if size(data,2) >= 3 && ~isempty(data{i,3})
        map(i).dst = char(string(data{i,3}));
    else
        map(i).dst = map(i).src;
    end
end

% sanitize: trim + fallback
for i=1:nCh
    map(i).src = strtrim(map(i).src);
    map(i).dst = strtrim(map(i).dst);
    if isempty(map(i).dst), map(i).dst = map(i).src; end
end

app.ChannelKeep{row} = map;
        end

        % Selection changed function: ChannelMappingTable
        function ChannelMappingTableSelectionChanged(app, event)
            
        end

        % Cell edit callback: ChannelMappingTable_2
        function ChannelMappingTable_2CellEdit(app, event)
data = app.ChannelMappingTable_2.Data;
sel  = app.UITable.Selection;
if isempty(sel) || isempty(data), return; end
row = sel(1,1);

nDS = size(data,1);
map(1,nDS) = struct('src','', 'dst','', 'keep', true);

for i = 1:nDS
    map(i).keep = ~isempty(data{i,1}) && logical(data{i,1});
    map(i).src  = char(string(data{i,2}));
    if size(data,2) >= 3 && ~isempty(data{i,3})
        map(i).dst = char(string(data{i,3}));
    else
        map(i).dst = map(i).src;
    end
end

for i=1:nDS
    map(i).src = strtrim(map(i).src);
    map(i).dst = strtrim(map(i).dst);
    if isempty(map(i).dst), map(i).dst = map(i).src; end
end

app.DataKeep{row} = map;


        end

        % Selection changed function: ChannelMappingTable_2
        function ChannelMappingTable_2SelectionChanged(app, event)
         
        end

        % Value changed function: minXEditField
        function minXEditFieldValueChanged(app, event)
            app.minXEditField.Value = round(app.minXEditField.Value);
    syncRectFromFields(app);
        end

        % Value changed function: minYEditField
        function minYEditFieldValueChanged(app, event)
          app.minYEditField.Value = round(app.minYEditField.Value);
    syncRectFromFields(app);
        end

        % Value changed function: widthEditField
        function widthEditFieldValueChanged(app, event)
          app.widthEditField.Value = round(app.widthEditField.Value);
    syncRectFromFields(app);
        end

        % Value changed function: heightEditField
        function heightEditFieldValueChanged(app, event)
       app.heightEditField.Value = round(app.heightEditField.Value);
    syncRectFromFields(app);
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

             proceed(app);

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
                     delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
              sel = app.UITable.Selection;
            if isempty(sel)
                return;
            end
            row = sel(1,1);
            if numel(app.Data.target) < row || isempty(app.Data.target{row})
                return;
            end
            tgt  = app.Data.target{row};
            rois = tgt.roi(:);
            if isempty(rois), return; end

            raw = app.UITable.Data{row,4};
            idx = parseROIString(app,raw, numel(rois));
            if isempty(idx)
                idx = 1:numel(rois);
            end

            % centre = celui de la première ROI
            r0 = rois(idx(1));
            val0 = r0.value(:)';
            cx = val0(1) + val0(3)/2;
            cy = val0(2) + val0(4)/2;

            % largeur / hauteur max
            widths  = []; heights = [];
            for k = idx(:)'
                rv = rois(k).value(:)';
                widths(end+1)  = rv(3); %#ok<AGROW>
                heights(end+1) = rv(4); %#ok<AGROW>
            end
            w = max(widths);
            h = max(heights);

            x = cx - w/2;
            y = cy - h/2;

            app.IsUpdatingFields = true;
            app.minXEditField.Value   = x;
            app.minYEditField.Value   = y;
            app.widthEditField.Value  = w;
            app.heightEditField.Value = h;
            app.IsUpdatingFields      = false;

            syncRectFromFields(app);
            saveCurrentRowSettings(app);
        end

        % Value changed function: BinningDropDown
        function BinningDropDownValueChanged(app, event)
               sel = app.UITable.Selection;
    if isempty(sel)
        return;
    end
    row = sel(1,1);

    bin = str2double(app.BinningDropDown.Value);
    if isnan(bin) || bin <= 0
        bin = 1;
    end

    % garde bbox existante si dispo
    st = app.ROISettings{row};
    if isempty(st) || ~isfield(st,'bbox') || isempty(st.bbox)
        bbox = [app.minXEditField.Value, app.minYEditField.Value,...
                app.widthEditField.Value, app.heightEditField.Value];
    else
        bbox = st.bbox;
    end

   mergeROISettings(app, row, struct('bbox',round(bbox), 'binning',bin));


    % réaffiche l'image avec le nouveau binning
    updatePolygonPreview(app);
            
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
              sel = app.UITable.Selection;
    if isempty(sel), return; end
    row = sel(1,1);

    fr = round(double(app.FramesSlider.Value(:)'));
    if numel(fr) ~= 2, return; end
    fr = [min(fr) max(fr)];

    % merge dans ROISettings
    st = [];
    if ~isempty(app.ROISettings) && row <= numel(app.ROISettings)
        st = app.ROISettings{row};
    end
    if isempty(st) || ~isstruct(st)
        st = struct();
    end
    st.framesRange = fr;
    app.ROISettings{row} = st;

    % label live
    try
        Tmax = app.FramesSlider.Limits(2);
    catch
        Tmax = NaN;
    end
    if isfinite(Tmax)
        app.FramesrangeLabel.Text = sprintf('Frames range: %d-%d / %d', fr(1), fr(2), Tmax);
    else
        app.FramesrangeLabel.Text = sprintf('Frames range: %d-%d', fr(1), fr(2));
    end
            
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
                sel = app.UITable.Selection;
    if isempty(sel), return; end
    row = sel(1,1);

    fr = round(double(event.Value(:)'));
    if numel(fr) ~= 2, return; end
    fr = [min(fr) max(fr)];

    % clamp si on a des limits
    Tmax = NaN;
    try, Tmax = double(app.FramesSlider.Limits(2)); catch, end
    if isfinite(Tmax)
        fr(1) = max(1, min(fr(1), Tmax));
        fr(2) = max(1, min(fr(2), Tmax));
        if fr(2) < fr(1), fr = fliplr(fr); end
        app.FramesrangeLabel.Text = sprintf('Frames range: %d-%d / %d', fr(1), fr(2), Tmax);
    else
        app.FramesrangeLabel.Text = sprintf('Frames range: %d-%d', fr(1), fr(2));
    end

    % mémorisation live (optionnel mais tu l’as demandé "en temps réel")
    mergeROISettings(app, row, struct('framesRange', fr));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select'; 'Project'; 'Classifier or Position (FOV)'; 'Select ROIs'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.ChannelMappingTable = uitable(app.UIFigure);
            app.ChannelMappingTable.ColumnName = {'Keep channel'; 'Source Channel Name'; 'Destination Channel Name'};
            app.ChannelMappingTable.RowName = {};
            app.ChannelMappingTable.ColumnEditable = [true false true];
            app.ChannelMappingTable.CellEditCallback = createCallbackFcn(app, @ChannelMappingTableCellEdit, true);
            app.ChannelMappingTable.SelectionChangedFcn = createCallbackFcn(app, @ChannelMappingTableSelectionChanged, true);
            app.minXEditFieldLabel = uilabel(app.UIFigure);
            app.minXEditFieldLabel.Text = 'minX';
            app.minXEditField = uieditfield(app.UIFigure, 'numeric');
            app.minXEditField.ValueChangedFcn = createCallbackFcn(app, @minXEditFieldValueChanged, true);
            app.minYEditFieldLabel = uilabel(app.UIFigure);
            app.minYEditFieldLabel.Text = 'minY';
            app.minYEditField = uieditfield(app.UIFigure, 'numeric');
            app.minYEditField.ValueChangedFcn = createCallbackFcn(app, @minYEditFieldValueChanged, true);
            app.widthEditFieldLabel = uilabel(app.UIFigure);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.UIFigure, 'numeric');
            app.widthEditField.ValueChangedFcn = createCallbackFcn(app, @widthEditFieldValueChanged, true);
            app.heightEditFieldLabel = uilabel(app.UIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightEditField.ValueChangedFcn = createCallbackFcn(app, @heightEditFieldValueChanged, true);
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChannelMappingTable_2 = uitable(app.UIFigure);
            app.ChannelMappingTable_2.ColumnName = {'Keep data'; 'Source Dataseries Name'; 'Destination Dataseries Name'};
            app.ChannelMappingTable_2.RowName = {};
            app.ChannelMappingTable_2.ColumnEditable = [true false true];
            app.ChannelMappingTable_2.CellEditCallback = createCallbackFcn(app, @ChannelMappingTable_2CellEdit, true);
            app.ChannelMappingTable_2.SelectionChangedFcn = createCallbackFcn(app, @ChannelMappingTable_2SelectionChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BinningDropDownLabel = uilabel(app.UIFigure);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.UIFigure);
            app.BinningDropDown.Items = {'1', '2', '4', '16'};
            app.BinningDropDown.ValueChangedFcn = createCallbackFcn(app, @BinningDropDownValueChanged, true);
            app.BinningDropDown.Value = '1';
            app.WarningLabel = uilabel(app.UIFigure);
            app.WarningLabel.Text = 'Warning';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure, 'range');
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesrangeLabel = uilabel(app.UIFigure);
            app.FramesrangeLabel.Text = 'Frames range:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIAdjustGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end