classdef ROIdisplayGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        StatusLabel      matlab.ui.control.Label
        HighSlider       matlab.ui.control.Slider
        HighSliderLabel  matlab.ui.control.Label
        LowSlider        matlab.ui.control.Slider
        LowSliderLabel   matlab.ui.control.Label
        CloseButton      matlab.ui.control.Button
        ChannelsLabel    matlab.ui.control.Label
        UITable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        function displayChannels(app)
            
            roiobj=app.Data.roiobj;

            nchannels=numel(roiobj.display.selectedchannel);


            t=app.UITable;
            
            
           %t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};

            
            d=t.Data;

            for i=1:nchannels

                if roiobj.display.selectedchannel(i)==1
                d{i,1}=true;
                else
                d{i,1}=false;
                end
            
                pix=roiobj.findChannelID(roiobj.display.channel{i});

                d{i,2}=roiobj.display.channel{i};

                d{i,3}='grayscale';
                tmp=round(65536*roiobj.display.displaylim(:,pix)); tmp=tmp';

                if numel(pix)==3
                d{i,3}='RGB image';
                tmp=round(mean(tmp,1));
                end

                d{i,4}=num2str(tmp);
                
                if numel(pix)==1 & sum(roiobj.display.intensity(i,:))==0
                d{i,3}='indexed image';
                d{i,4}='N/A';
                end

                d{i,5}=false;
            end


            t.Data=d;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roiobj, him, hp, classif, h)
            app.Data.roiobj=roiobj;
            app.Data.him=him;
            app.Data.hp=hp;
            app.Data.h=h;
            app.Data.classif=classif;

            app.UIFigure.Name=['Display settings for ROI: ' roiobj.id];

            app.LowSlider.Limits=[0 65536];
            app.HighSlider.Limits=[0 65536];

              t=app.UITable;
            
              t.Data=[];
            
           %t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};

            t.ColumnWidth={100, 'auto', 100, 'auto' 100};
            t.ColumnEditable=[true false false true true];

            displayChannels(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            roiobj=app.Data.roiobj; 
            h=app.Data.h;
            hp=app.Data.hp;
            him=app.Data.him;
            classif=app.Data.classif; 
            
            if indices(2)==1 % check box pressed
                roiobj.display.selectedchannel(indices(1))=newData; 
                 clf
            h.UserData=[];
            [him hp]=draw(roiobj,h,classif);
            app.Data.him=him;
            app.Data.hp=hp;
            end

            if indices(2)==4 %changed intensity levels
 
                tm=str2num(newData);

                if numel(tm)==0
                    return;
                end
                
                 pix=roiobj.findChannelID(roiobj.display.channel{indices(1)});

                 if numel(pix)==3 % rgb image

                     tw=repmat(tm', [1 3]);
                roiobj.display.displaylim(:,pix)=double(tw)./65536;

                 else
                 roiobj.display.displaylim(:,indices(1))=double(tm')./65536;
                 end

                updatedisplay(roiobj,him,hp,classif);
            end

  
                if indices(2)==5% auto adjust levels
                    
                    pix=find(roiobj.channelid==indices(1));

                    if numel(pix)
                            roiobj.computeDisplaylim('Channel',pix);

                              t=app.UITable;
              
                              t.Data{indices(1),5}=false;
                              t.Data{indices(1),4}=num2str( [ uint16(65536*roiobj.display.displaylim(1,indices(1)))  uint16(65536*roiobj.display.displaylim(2,indices(1))) ]   ); % HERE
                            app.UITable=t;

                              updatedisplay(roiobj,him,hp,classif);
                    end
                    

               end


            UITableSelectionChanged(app, event)
            

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: LowSlider
        function LowSliderValueChanged(app, event)
            value = app.LowSlider.Value;
            roiobj=app.Data.roiobj;

            % HERE : change scaling of values 
            
           % value=65536*(exp(1*value/65536)-exp(0))./exp(1)

            value = 65535*(value/65535)^5;

            indices=app.UITable.Selection;

            if numel(indices)==0
                      app.StatusLabel.Text='No selection';
                return;
          
            end

           if roiobj.display.selectedchannel(indices(1))==0
                     app.StatusLabel.Text='Channel not displayed!';
               return;
           end


            if value>65536*roiobj.display.displaylim(2,indices(1))
                  app.StatusLabel.Text='High larger than low';
                 return;
            end


            roiobj=app.Data.roiobj; 
            h=app.Data.h;
            hp=app.Data.hp;
            him=app.Data.him;
            classif=app.Data.classif; 
            

                pix=roiobj.findChannelID(roiobj.display.channel{indices(1)});

                 if numel(pix)==3 % rgb image
     
                 roiobj.display.displaylim(1,pix)=value./65536*ones(1,3);

                 else
                roiobj.display.displaylim(1,indices(1))=value./65536;
                 end


            displayChannels(app)
            updatedisplay(roiobj,him,hp,classif);

                app.StatusLabel.Text='OK';
        end

        % Value changed function: HighSlider
        function HighSliderValueChanged(app, event)
            value = app.HighSlider.Value;
            roiobj=app.Data.roiobj;

          value = 65535*(value/65535)^5;
             indices=app.UITable.Selection;

         if numel(indices)==0
                      app.StatusLabel.Text='No selection';
                return;
          
            end

           if roiobj.display.selectedchannel(indices(1))==0
                     app.StatusLabel.Text='Channel not displayed!';
               return;
           end


            if value<65536*roiobj.display.displaylim(1,indices(1))
                  app.StatusLabel.Text='High lower than low';
                 return;
            end


            roiobj=app.Data.roiobj; 
            h=app.Data.h;
            hp=app.Data.hp;
            him=app.Data.him;
            classif=app.Data.classif; 
            
                   pix=roiobj.findChannelID(roiobj.display.channel{indices(1)});

                 if numel(pix)==3 % rgb image
     
                 roiobj.display.displaylim(2,pix)=value./65536*ones(1,3);

                 else
                roiobj.display.displaylim(2,indices(1))=value./65536;
                 end


         % roiobj.display.displaylim(2,indices(1))=value./65536;

            displayChannels(app)
            updatedisplay(roiobj,him,hp,classif);
            
                app.StatusLabel.Text='OK';
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
  
            t=app.UITable.Data;

           if numel( selection)==0
               return
           end

            if selection(1,1)>size(t,1)
                return
            end

            cur=str2num(t{selection(1,1),4});

            if numel(cur)==0
                return;
            end

          
            app.LowSlider.Value=max(0,65536*(cur(1)/65535)^(1/5));
            app.HighSlider.Value=min(65536,65536*(cur(2)/65536)^(1/5));
            

        %    val=ap
        end

        % Value changing function: LowSlider
        function LowSliderValueChanging(app, event)
            value = event.Value;
             roiobj=app.Data.roiobj;

            % HERE : change scaling of values 
            
           % value=65536*(exp(1*value/65536)-exp(0))./exp(1)

            value = 65535*(value/65535)^5;

            indices=app.UITable.Selection;

            if numel(indices)==0
                      app.StatusLabel.Text='No selection';
                return;
          
            end

           if roiobj.display.selectedchannel(indices(1))==0
                     app.StatusLabel.Text='Channel not displayed!';
               return;
           end


            if value>65536*roiobj.display.displaylim(2,indices(1))
                  app.StatusLabel.Text='High larger than low';
                 return;
            end


            roiobj=app.Data.roiobj; 
            h=app.Data.h;
            hp=app.Data.hp;
            him=app.Data.him;
            classif=app.Data.classif; 
            

                pix=roiobj.findChannelID(roiobj.display.channel{indices(1)});

                 if numel(pix)==3 % rgb image
     
                 roiobj.display.displaylim(1,pix)=value./65536*ones(1,3);

                 else
                roiobj.display.displaylim(1,indices(1))=value./65536;
                 end


            displayChannels(app)
            updatedisplay(roiobj,him,hp,classif);

                app.StatusLabel.Text='OK'; 


        end

        % Value changing function: HighSlider
        function HighSliderValueChanging(app, event)
            value = event.Value;
             roiobj=app.Data.roiobj;

          value = 65535*(value/65535)^5;
             indices=app.UITable.Selection;

         if numel(indices)==0
                      app.StatusLabel.Text='No selection';
                return;
          
            end

           if roiobj.display.selectedchannel(indices(1))==0
                     app.StatusLabel.Text='Channel not displayed!';
               return;
           end


            if value<65536*roiobj.display.displaylim(1,indices(1))
                  app.StatusLabel.Text='High lower than low';
                 return;
            end


            roiobj=app.Data.roiobj; 
            h=app.Data.h;
            hp=app.Data.hp;
            him=app.Data.him;
            classif=app.Data.classif; 
            
                   pix=roiobj.findChannelID(roiobj.display.channel{indices(1)});

                 if numel(pix)==3 % rgb image
     
                 roiobj.display.displaylim(2,pix)=value./65536*ones(1,3);

                 else
                roiobj.display.displaylim(2,indices(1))=value./65536;
                 end


         % roiobj.display.displaylim(2,indices(1))=value./65536;

            displayChannels(app)
            updatedisplay(roiobj,him,hp,classif);
            
                app.StatusLabel.Text='OK';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Display'; 'Name'; 'Channel Type'; 'Levels'; 'Auto-adjust'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.ChannelsLabel = uilabel(app.UIFigure);
            app.ChannelsLabel.Text = 'Channels';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.LowSliderLabel = uilabel(app.UIFigure);
            app.LowSliderLabel.Text = 'Low';
            app.LowSlider = uislider(app.UIFigure);
            app.LowSlider.MajorTicks = [];
            app.LowSlider.ValueChangedFcn = createCallbackFcn(app, @LowSliderValueChanged, true);
            app.LowSlider.ValueChangingFcn = createCallbackFcn(app, @LowSliderValueChanging, true);
            app.HighSliderLabel = uilabel(app.UIFigure);
            app.HighSliderLabel.Text = 'High';
            app.HighSlider = uislider(app.UIFigure);
            app.HighSlider.MajorTicks = [];
            app.HighSlider.ValueChangedFcn = createCallbackFcn(app, @HighSliderValueChanged, true);
            app.HighSlider.ValueChangingFcn = createCallbackFcn(app, @HighSliderValueChanging, true);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIdisplayGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end