classdef addDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DataselectioncommandsPanel  matlab.ui.container.Panel
        CloseButton                 matlab.ui.control.Button
        DatalabelEditField          matlab.ui.control.EditField
        DatalabelEditFieldLabel     matlab.ui.control.Label
        ParsingrulesLabel           matlab.ui.control.Label
        ParsefilelistButton         matlab.ui.control.Button
        AddselectedpositionstoprojectButton  matlab.ui.control.Button
        ParserinputLabel            matlab.ui.control.Label
        ParseroutputLabel           matlab.ui.control.Label
        UITableFilter               matlab.ui.control.Table
        Label_2                     matlab.ui.control.Label
        ParsingcommentsLabel        matlab.ui.control.Label
        filenameLabel               matlab.ui.control.Label
        SamplefilenameLabel         matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        SelectdatadirectoryPanel    matlab.ui.container.Panel
        ChooseOMEzarrOMEtiffButton  matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        ChoosedirectoryButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        project % Description
        output=[]; % Description
        com % Description
        mainapp % Description
        path=[]; % Description
    end
    
    methods (Access = private)
        
        function s = getSampleFilename(app, pos)
    % Retourne une string safe pour afficher un "sample filename"
    s = '';

    % Cas multi-TIFF récent (avec tiffSource/pageMap)
    if isfield(pos,'isMultiTiff') && pos.isMultiTiff
        if isfield(pos,'tiffSource') && ~isempty(pos.tiffSource)
            try
                if iscell(pos.tiffSource) && ~isempty(pos.tiffSource{1})
                    s = pos.tiffSource{1};
                    return;
                elseif ischar(pos.tiffSource) || isstring(pos.tiffSource)
                    s = char(pos.tiffSource);
                    return;
                end
            catch
                % continue fallback
            end
        end
        % fallback: nom de la position (souvent le fichier)
        if isfield(pos,'name') && ~isempty(pos.name)
            s = char(string(pos.name));
            return;
        end
    end

    % Cas générique : filelist peut être cell, struct array, ou cell-of-struct-array
    if isfield(pos,'filelist') && ~isempty(pos.filelist)
        try
            fl = pos.filelist;

            % filelist = cell
            if iscell(fl)
                x = fl{1};
                if isstruct(x) && ~isempty(x) && isfield(x,'name')
                    s = x(1).name;
                    return;
                end
                if isstruct(fl{1}) && isfield(fl{1},'name')
                    s = fl{1}.name;
                    return;
                end
            end

            % filelist = struct array
            if isstruct(fl) && ~isempty(fl) && isfield(fl,'name')
                s = fl(1).name;
                return;
            end
        catch
            % continue fallback
        end
    end

    % Dernier fallback
    if isfield(pos,'name') && ~isempty(pos.name)
        s = char(string(pos.name));
    end
end


        function displayresults(app,option)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
        'Message','Parsing files in the selected directory...');
            d.Value=0.33;
            
            pathe=app.path;
            
            app.Label.Text=pathe;
            
           switch nargin 
               case 1 % default call to parser : no specific filter
                    app.output=parseInputData(pathe,'progress',d); % parses data 
            
                 %aa=   app.output
               case 2 % in this case, gather filter from table 
    
                   data=app.UITableFilter.Data; 
                   
                   posfilter=data(:,1);
                   tmp={};
                   for i=1:numel(posfilter)
                       if numel(posfilter{i})~=0
                           tmp=[tmp posfilter{i}];
                       end
                   end
                   posfilter=tmp;
                   
                   arg={};
                   if numel(posfilter)
                       arg=[arg {'positionfilter'} {posfilter}];
                   end
                   
                   chafilter=data(:,2);
                   tmp={};
                   for i=1:numel(chafilter)
                       if numel(chafilter{i})~=0
                           tmp{i}= chafilter{i};
                       end
                   end
                   chafilter=tmp;
                   
                   if numel(chafilter)
                        arg=[arg {'channelfilter'} {chafilter}] ;
                   end
                   
                   stackfilter=data(:,3);
                   tmp={};
                   for i=1:numel(stackfilter)
                       if numel(stackfilter{i})~=0
                           tmp{i}=stackfilter{i};
                       end
                   end
                   
                   stackfilter=tmp;
                   
                    if numel(stackfilter)
                        arg=[arg {'stackfilter'} {stackfilter}] ;
                    end
                    
                    arg=[arg {'progress'} {d}];
                    
                  %  arg
                    app.output=parseInputData(pathe,arg{:});   
           end
           
          % pix= app.output.pos(1)
            
            d.Message='Displaying results...';
            pause(0.5);
            
            % display parser output if no phylocell project
            
            if ~strcmp(app.output.datatype,'phylocell') && ~strcmp(app.output.datatype,'multitif')
                
             
                app.UITableFilter.Enable='on';
                app.ParsefilelistButton.Enable='on';
                app.UITableFilter.ColumnEditable=[true true true];
                
                data=app.UITableFilter.Data;
                if numel(data)
                data={'' '' ''; '' '' ''; '' '' ''; '' '' ''};
                end
                
                posfilter=app.output.pos(1).positionfilter2;
                
                if numel(posfilter)
                   for i=1:numel(posfilter)  
                   data{i,1}=posfilter{i};
                   end
                end
                
                chafilter=app.output.pos(1).channelfilter2;

                
                if numel(chafilter)
                    for i=1:numel(chafilter)
                        data{i,2}=chafilter{i};
                    end
                end
                
                stackfilter=app.output.pos(1).stackfilter2;
                
                if numel(stackfilter)
                     for i=1:numel(stackfilter)
                data{i,3}=stackfilter{i};
                     end
                end
   
                
                app.UITableFilter.Data=data;
                app.UITableFilter.Enable='on';
            end
            
            
          %  if app.output.data==true
           [~ ,tmp, ~]=fileparts(app.path);
            app.DatalabelEditField.Value=tmp;
            
            plotresults(app);
         %   else
          %  app.Label_2.Text=app.output.comments;   
          %  end
           
          
            close(d);
        end
        
        function plotresults(app)
            % display the result of parsing in the bottom panel 
            app.Label_2.Text=app.output.comments;
            app.UITable.Enable='on';
            
           
            tmp={};
            for i=1:numel(app.output.pos) 
                tmp{i,1}=true;
                tmp{i,2}=app.output.pos(i).name;
                tmp{i,3}=app.output.pos(i).channels;
                tmp{i,4}=num2str(app.output.pos(i).frames);
            end
            
          %  tmp
            app.UITable.Data=tmp;
            ncols = size(app.UITable.Data,2);
            app.UITable.ColumnEditable = true(1,ncols); %all columns editable
            app.UITable.ColumnEditable(2:4) = false; %column2 non-editable

          if ~isempty(app.output) && isfield(app.output,'pos') && ~isempty(app.output.pos)
    app.filenameLabel.Text = getSampleFilename(app, app.output.pos(1));
end

           
           % app.filenameLabel.Text=datetime;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj, mainapp)
            app.project=shallowObj;
            
           
                data={'' '' ''; '' '' ''; '' '' ''; '' '' ''};
                app.UITableFilter.Data=data;
               
                
            if nargin==3
            app.mainapp=mainapp;
            end
        end

        % Callback function
        function ChoosedirectoryButtonPushed(app, event)
           
            pathe = uigetdir(pwd,'Select directory with data:');
    
            if pathe==0
                disp('Quit!');
            return;
            end

          app.path=pathe;
          displayresults(app);
  
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;

           app.ChannelfilterstringEditField.Value=app.output.pos(indices(1)).channelfilter{1};
           app.StacksfilterstringEditField.Value=app.output.pos(indices(1)).stackfilter{1};
           
           if ~isempty(indices)
    app.filenameLabel.Text = getSampleFilename(app, app.output.pos(indices(1)));
end

           app.com=indices(1);
           
           %app.ChannelfilterstringEditField.Value=app.output.pos(app.com).channelfilter;
           %app.StacksfilterstringEditField.Value=app.output.pos(app.com).stackfilter;
        end

        % Callback function
        function ChannelfilterstringEditFieldValueChanged(app, event)
        
        end

        % Callback function
        function StacksfilterstringEditFieldValueChanged(app, event)
  
        end

        % Callback function
        function ChannelfilterstringEditFieldValueChanging(app, event)
     
        end

        % Callback function
        function StacksfilterstringEditFieldValueChanging(app, event)

        end

        % Callback function
        function UIFigureButtonDown(app, event)
          
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %plotresults(app);
        end

        % Button pushed function: AddselectedpositionstoprojectButton
        function AddselectedpositionstoprojectButtonPushed(app, event)
%             if strcmp(app.AddselectedpositionstoprojectButton.Text,'Close')
%                 %app.mainapp.gatherVarsFromWorkspace;
%                 %app.mainapp.displayNodes;
%                 uiresume(app.mainapp.DetecDivUIFigure)
%                 app.delete;
%                 return
%             end
            
            if numel(app.UITable.Data)==0
                uialert(app.UIFigure,'There are no data to be added !','Error')
                return;
            end
            
            selected=find(cellfun(@(x) x==1,app.UITable.Data(:,1)));
            
            if numel(selected)==0
                uialert(app.UIFigure,'You must select at least one item !','Warning');
                return;
            end
            
            app.output.pos=app.output.pos(selected);
            
            if numel(app.output)
                lab=app.DatalabelEditField.Value; 
                
                if numel(lab)
                    for i=1:numel(app.output.pos)
                        app.output.pos(i).name=[lab '_' app.output.pos(i).name];
                    end
                end
                
           app.project.addData(app.output)
           shallowSave(app.project);
           uialert(app.UIFigure,'Data successfully added! Press the Close button to quit...','Success','Icon','success');


          %  app.AddselectedpositionstoprojectButton.Text='Close';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %'ok'
             
            uiresume(app.mainapp.DetecDivUIFigure)
            delete(app)
            
        end

        % Button pushed function: ChoosedirectoryButton
        function ChoosedirectoryButtonPushed2(app, event)
            pathe=uigetdir(pwd);
            
            if pathe~=0
                app.path=pathe; 
                displayresults(app)
            end
        end

        % Button pushed function: ChooseOMEzarrOMEtiffButton
        function ChooseOMEzarrOMEtiffButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, ...
                'Choose whether to import an OME-Zarr folder, a Zarr index file, or an OME-TIFF file.', ...
                'Select data source', ...
                'Options', {'OME-Zarr folder', 'Zarr index file', 'OME-TIFF file', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 4);

            switch choice
                case 'OME-Zarr folder'
                    pathe = uigetdir(pwd, 'Select OME-Zarr folder');
                    if isequal(pathe, 0)
                        return;
                    end
                    app.path = pathe;

                case 'Zarr index file'
                    [file, folder] = uigetfile( ...
                        {'zarr.json', 'Zarr index file (zarr.json)'; ...
                         '*.json', 'JSON files (*.json)'; ...
                         '*.*', 'All files (*.*)'}, ...
                        'Select Zarr index file', pwd);
                    if isequal(file, 0) || isequal(folder, 0)
                        return;
                    end
                    app.path = fullfile(folder, file);

                case 'OME-TIFF file'
                    [file, folder] = uigetfile( ...
                        {'*.ome.tif;*.ome.tiff;*.tif;*.tiff', 'OME-TIFF / TIFF files (*.ome.tif, *.ome.tiff, *.tif, *.tiff)'; ...
                         '*.*', 'All files (*.*)'}, ...
                        'Select OME-TIFF file', pwd);
                    if isequal(file, 0) || isequal(folder, 0)
                        return;
                    end
                    app.path = fullfile(folder, file);

                otherwise
                    return;
            end

            displayresults(app)
        end

        % Button pushed function: ParsefilelistButton
        function ParsefilelistButtonPushed(app, event)
            displayresults(app,'ok')
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
              uiresume(app.mainapp.DetecDivUIFigure)
            delete(app)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.SelectdatadirectoryPanel = uipanel(app.UIFigure);
            app.SelectdatadirectoryPanel.Title = 'Select data directory';
            app.ChoosedirectoryButton = uibutton(app.SelectdatadirectoryPanel, 'push');
            app.ChoosedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosedirectoryButtonPushed2, true);
            app.ChoosedirectoryButton.Text = 'Choose directory....';
            app.Label = uilabel(app.SelectdatadirectoryPanel);
            app.Label.Text = '';
            app.ChooseOMEzarrOMEtiffButton = uibutton(app.SelectdatadirectoryPanel, 'push');
            app.ChooseOMEzarrOMEtiffButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseOMEzarrOMEtiffButtonPushed, true);
            app.ChooseOMEzarrOMEtiffButton.Text = 'Choose OME-zarr / OME-tiff...';
            app.DataselectioncommandsPanel = uipanel(app.UIFigure);
            app.DataselectioncommandsPanel.Title = 'Data selection commands';
            app.UITable = uitable(app.DataselectioncommandsPanel);
            app.UITable.ColumnName = {'Select'; 'Position'; '# Channels'; '#Frames'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Enable = 'off';
            app.SamplefilenameLabel = uilabel(app.DataselectioncommandsPanel);
            app.SamplefilenameLabel.Text = 'Sample file name: ';
            app.filenameLabel = uilabel(app.DataselectioncommandsPanel);
            app.filenameLabel.Text = 'filename';
            app.ParsingcommentsLabel = uilabel(app.DataselectioncommandsPanel);
            app.ParsingcommentsLabel.Text = 'Parsing comments:';
            app.Label_2 = uilabel(app.DataselectioncommandsPanel);
            app.Label_2.Text = '';
            app.UITableFilter = uitable(app.DataselectioncommandsPanel);
            app.UITableFilter.ColumnName = {'Positions'; 'Channels'; 'Stacks'};
            app.UITableFilter.RowName = {};
            app.UITableFilter.Enable = 'off';
            app.ParseroutputLabel = uilabel(app.DataselectioncommandsPanel);
            app.ParseroutputLabel.Text = 'Parser output:';
            app.ParserinputLabel = uilabel(app.DataselectioncommandsPanel);
            app.ParserinputLabel.Text = 'For each category, please enter a string to extract positions/channels/stacks. Example : for channels, either write ''GFP'', ''mCherry'' or ''cha$'' to look for numerated channels in the form ''cha1'', ''cha2'' etc.';
            app.AddselectedpositionstoprojectButton = uibutton(app.DataselectioncommandsPanel, 'push');
            app.AddselectedpositionstoprojectButton.ButtonPushedFcn = createCallbackFcn(app, @AddselectedpositionstoprojectButtonPushed, true);
            app.AddselectedpositionstoprojectButton.Text = 'Add selected positions to project';
            app.ParsefilelistButton = uibutton(app.DataselectioncommandsPanel, 'push');
            app.ParsefilelistButton.ButtonPushedFcn = createCallbackFcn(app, @ParsefilelistButtonPushed, true);
            app.ParsefilelistButton.Enable = 'off';
            app.ParsefilelistButton.Text = 'Parse filelist !';
            app.ParsingrulesLabel = uilabel(app.DataselectioncommandsPanel);
            app.ParsingrulesLabel.Text = 'Parsing rules:';
            app.DatalabelEditFieldLabel = uilabel(app.DataselectioncommandsPanel);
            app.DatalabelEditFieldLabel.Text = 'Data label:';
            app.DatalabelEditField = uieditfield(app.DataselectioncommandsPanel, 'text');
            app.CloseButton = uibutton(app.DataselectioncommandsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addDataGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end