classdef detecdiv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DetecDivUIFigure               matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewprojectMenu                 matlab.ui.container.Menu
        OpenprojectMenu                matlab.ui.container.Menu
        CatalogBrowserMenu            matlab.ui.container.Menu
        OpenrecentMenu                 matlab.ui.container.Menu
        Item1Menu                      matlab.ui.container.Menu
        SaveselectedprojectMenu        matlab.ui.container.Menu
        Closeproject                   matlab.ui.container.Menu
        ReloadprojectfromdiskMenu      matlab.ui.container.Menu
        ExportprojecttoPhylocellMenu   matlab.ui.container.Menu
        NewprojectindependentclassiiferMenu  matlab.ui.container.Menu
        OpenprojectindependentclassifierMenu  matlab.ui.container.Menu
        OpenrecentClassiMenu           matlab.ui.container.Menu
        SaveprojectindependentclassifierMenu  matlab.ui.container.Menu
        CloseprojectindependentclassifierMenu  matlab.ui.container.Menu
        ClassifierRepositoryMenu       matlab.ui.container.Menu
        PipelineTemplatesMenu          matlab.ui.container.Menu
        NewpipelinetemplateMenu        matlab.ui.container.Menu
        OpenpipelinetemplateMenu       matlab.ui.container.Menu
        OpenrecentPipelineMenu         matlab.ui.container.Menu
        ClosepipelinetemplateMenu      matlab.ui.container.Menu
        ConvertoldtrainingandresultdataMenu  matlab.ui.container.Menu
        FunctionsMenu                  matlab.ui.container.Menu
        ClassifyDataMenu               matlab.ui.container.Menu
        ProcessdataMenu                matlab.ui.container.Menu
        ExportdataMenu                 matlab.ui.container.Menu
        MakeROImoviesMenu              matlab.ui.container.Menu
        ProjectMenu                    matlab.ui.container.Menu
        PositionsMenu                  matlab.ui.container.Menu
        AddPositionsDataMenu           matlab.ui.container.Menu
        SetFrameOrientationMenu        matlab.ui.container.Menu
        CreateFullFrameROisMenu        matlab.ui.container.Menu
        DeleteROIsMenu                 matlab.ui.container.Menu
        AdjustROIsMenu                 matlab.ui.container.Menu
        DeletePositionsMenu_2          matlab.ui.container.Menu
        RestoredeletedROIsMenu         matlab.ui.container.Menu
        ForcerawdatapathrebaseMenu     matlab.ui.container.Menu
        ClassifiersMenu                matlab.ui.container.Menu
        AddclassifierMenu              matlab.ui.container.Menu
        DeleteclassifierMenu           matlab.ui.container.Menu
        AdjustROIsMenu_3               matlab.ui.container.Menu
        DeleteROIsMenu_2               matlab.ui.container.Menu
        RestoredeletedROIsMenu_2       matlab.ui.container.Menu
        AdjustROIsMenu_2               matlab.ui.container.Menu
        DisplayMenu                    matlab.ui.container.Menu
        RefreshtreewindowMenu          matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        UserpreferencesMenu            matlab.ui.container.Menu
        ProjectsPanel                  matlab.ui.container.Panel
        InspectRunButton               matlab.ui.control.Button
        EditRunButton                  matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        ProcessdataButton              matlab.ui.control.Button
        AddprocessorButton             matlab.ui.control.Button
        ClassifydataButton             matlab.ui.control.Button
        UpdaterawdatapathButton        matlab.ui.control.Button
        ExtractROIhypervolumesButton   matlab.ui.control.Button
        IdentifyROIsinpositionsButton  matlab.ui.control.Button
        ProjectInformationLabel        matlab.ui.control.Label
        AddclassifierButton            matlab.ui.control.Button
        AdddataButton                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Tree                           matlab.ui.container.Tree
        ProjectsNode                   matlab.ui.container.TreeNode
        IndependentClassifiersNode     matlab.ui.container.TreeNode
        PipelinesNode                  matlab.ui.container.TreeNode
    end


    properties (Access = private)
        Data % Description
        RecentProjects string = strings(0)  % liste des chemins absolus récents
        RecentProjectsFile string          % chemin complet du .mat persistant
        RecentClassifiers string = strings(0)    % chemins complets vers *_classification.mat
        RecentClassifiersFile string             % stockage persistant
        RecentPipelines string = strings(0)      % chemins complets vers pipeline.json
        RecentPipelinesFile string               % stockage persistant
        ActivePipelineCancelFile string = ""
        ActivePipelineCancelTimer = []
        ActivePipelineProgressDialog = []
        RoiTreeBuildTimer = []
        RoiTreeBuildDialog = []
        RoiTreeBuildState = struct()
        MainGrid matlab.ui.container.GridLayout
        WorkspaceEventListenerId string = ""

    end

    methods (Access = public)

        function displayNodes(app)
            app.stopRoiTreeBuild();
            cc=1;
            %h1=[];
            %hproj=[];
            %hclassi=[];

            if ~app.componentExists('ProjectsNode') || ~app.componentExists('IndependentClassifiersNode')
                return;
            end

            app.deleteNodeChildrenIfPresent('ProjectsNode');
            app.deleteNodeChildrenIfPresent('IndependentClassifiersNode');
            app.deleteNodeChildrenIfPresent('PipelinesNode');

            [pth fle ext]= fileparts(which('detecdiv.mlapp'));

            for i=1:numel(app.Data.Project)
                cmProject = uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cmProject,'Text','Relink raw data...');
                m.MenuSelectedFcn = {@contextMenuRelinkRawDataFcn,i,'Project'};
                m = uimenu(cmProject,'Text','Refresh Hub lock information');
                m.MenuSelectedFcn = {@contextMenuRefreshProjectHubLockFcn,i,'Project'};
                m = uimenu(cmProject,'Text','Release Hub lock and edit here...');
                m.MenuSelectedFcn = {@contextMenuReleaseProjectHubLockFcn,i,'Project'};
                h1(i)=uitreenode(app.ProjectsNode,'Text',app.Data.Project{i},'Tag','Project','UserData',i, ...
                    'ContextMenu',cmProject,'Icon',fullfile(pth,'detecDiv_logo.png'));


                for k=1:numel(app.Data.Projectclassi{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open classifier...');
                    m.MenuSelectedFcn={@contextMenuClassiFcn,[i,k],'Projectclassi'};
                    m = uimenu(cm,'Text','Delete classifier');
                    m.MenuSelectedFcn={@contextMenuDeleteClassiFcn,[i,k],'Projectclassi'};
                    m = uimenu(cm,'Text','Delete ROIs...');
                    m.MenuSelectedFcn={@contextMenuDeleteROIsFcn,[i,k],'Projectclassi'};

                    g1(i,k)=uitreenode(h1(i),'Text',app.Data.Projectclassi{i}{k},'Tag','Projectclassi','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'brain.png'));


                    %                  if numel(app.Data.Projectclassirois{i})
                    %                    for n=1:numel(app.Data.Projectclassirois{i}{k})
                    %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                    %                       cm=uicontextmenu(app.DetecDivUIFigure);
                    %                     m = uimenu(cm,'Text','Open ROI...');
                    %                     m.MenuSelectedFcn={@contextMenuROIFcn,[i,k,n],'Projectclassirois'};
                    %                     ''ContextMenu',cm'
                    %                   uitreenode(g1(i,k),'Text',app.Data.Projectclassirois{i}{k}{n},'Tag','Projectclassirois','UserData',[i,k,n],'Icon',fullfile(pth,'roi.png'));
                    %                                     % disabled because too heavy with large projects
                    %                   end
                    %                 end


                end

                for k=1:numel(app.Data.Projectprocess{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open processor...');
                    m.MenuSelectedFcn={@contextMenuProcessFcn,[i,k],'Projectprocess'};
                    m = uimenu(cm,'Text','Duplicate processor...');
                    m.MenuSelectedFcn = {@contextMenuDuplicateProcessFcn,[i,k],'Projectprocess'};
                    m = uimenu(cm,'Text','Delete processor');
                    m.MenuSelectedFcn={@contextMenuDeleteProcessFcn,[i,k],'Projectprocess'};
                    g1(i,k)=uitreenode(h1(i),'Text',app.Data.Projectprocess{i}{k},'Tag','Projectprocess','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'processor.png'));


                    %                  if numel(app.Data.Projectclassirois{i})
                    %                    for n=1:numel(app.Data.Projectclassirois{i}{k})
                    %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                    %                       cm=uicontextmenu(app.DetecDivUIFigure);
                    %                     m = uimenu(cm,'Text','Open ROI...');
                    %                     m.MenuSelectedFcn={@contextMenuROIFcn,[i,k,n],'Projectclassirois'};
                    %                     ''ContextMenu',cm'
                    %                   uitreenode(g1(i,k),'Text',app.Data.Projectclassirois{i}{k}{n},'Tag','Projectclassirois','UserData',[i,k,n],'Icon',fullfile(pth,'roi.png'));
                    %                                     % disabled because too heavy with large projects
                    %                   end
                    %                 end


                end


                for k=1:numel(app.Data.Projectpos{i})
                    cm=uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cm,'Text','Open position...');
                    m.MenuSelectedFcn={@contextMenuPositionFcn,[i,k],'Projectpos'};
                    m = uimenu(cm,'Text','Relink raw data for this position...');
                    m.MenuSelectedFcn={@contextMenuRelinkRawDataFcn,[i,k],'Projectpos'};
                    m = uimenu(cm,'Text','Delete ROIs...');
                    m.MenuSelectedFcn={@contextMenuDeleteROIsFcn,[i,k],'Projectpos'};
                    m = uimenu(cm,'Text','Delete current position');
                    m.MenuSelectedFcn={@contextMenuDeletePositionFcn,[i,k],'Projectpos'};

                    g2(i,k)=uitreenode(h1(i),'Text',app.Data.Projectpos{i}{k},'Tag','Projectpos','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'data.png'));

                    %                      if numel(app.Data.Projectposrois{i})
                    %
                    %                     for n=1:numel(app.Data.Projectposrois{i}{k})
                    %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                    %                    %   cm=uicontextmenu(app.DetecDivUIFigure);
                    %                   %  m = uimenu(cm,'Text','Open ROI...');
                    %                   %  m.MenuSelectedFcn={@contextMenuROIFcn,[i,k,n],'Projectposrois'};
                    %
                    %                 %        uitreenode(g2(i,k),'Text',app.Data.Projectposrois{i}{k}{n},'Tag','Projectposrois','UserData',[i,k,n],'Icon',fullfile(pth,'roi.png'));
                    %                       % disabled because too heavy with large projects
                    %                     end
                    %                      end

                end

                if i <= numel(app.Data.ProjectpipelineRun) && ~isempty(app.Data.ProjectpipelineRun{i})
                    cmRunRoot = uicontextmenu(app.DetecDivUIFigure);
                    m = uimenu(cmRunRoot,'Text','Delete all runs...');
                    m.MenuSelectedFcn={@contextMenuDeleteAllPipelineRunsFcn,i,'ProjectpipelineRunRoot'};
                    runRoot = uitreenode(h1(i),'Text','Run','Tag','ProjectpipelineRunRoot', ...
                        'UserData',i,'ContextMenu',cmRunRoot,'Icon',fullfile(pth,'pipeline_run.png'));
                    for k=1:numel(app.Data.ProjectpipelineRun{i})
                        createPipelineRunTreeNode(runRoot, i, k, pth);
                    end
                end

            end

            loadedPipelineKeys = containers.Map('KeyType','char','ValueType','logical');
            if app.componentExists('PipelinesNode')
                for i=1:numel(app.Data.Pipeline)
                    if isempty(app.Data.Pipeline{i})
                        continue;
                    end
                    createPipelineTreeNode(app.PipelinesNode, i, pth);
                    try
                        pipeObj = getPipelineByIndex(i);
                        key = app.normalizePipelineRootPath(pipeObj.path);
                        if ~isempty(key)
                            loadedPipelineKeys(key) = true;
                        end
                    catch
                    end
                end

                for iRecent = 1:numel(app.RecentPipelines)
                    pipelineJson = char(string(app.RecentPipelines(iRecent)));
                    key = app.normalizePipelineRootPath(pipelineJson);
                    if isempty(key) || isKey(loadedPipelineKeys, key)
                        continue;
                    end
                    createRecentPipelineTreeNode(app.PipelinesNode, pipelineJson, pth);
                end
            end

            for i=1:numel(app.Data.Classifier)
                cm=uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cm,'Text','Open classifier...');
                m.MenuSelectedFcn={@contextMenuClassiFcn,i,'Classifier'};
                m = uimenu(cm,'Text','Close classifier');
                m.MenuSelectedFcn={@contextMenuDeleteClassiFcn,i,'Classifier'};
                m = uimenu(cm,'Text','Delete ROIs...');
                m.MenuSelectedFcn={@contextMenuDeleteROIsFcn,i,'Classifier'};

                g3(i)=uitreenode(app.IndependentClassifiersNode,'Text',app.Data.Classifier{i},'Tag','Classifier','UserData',i,'ContextMenu',cm,'Icon',fullfile(pth,'brain.png'));

                if isfield(app.Data,'ClassifierpipelineRun') && i <= numel(app.Data.ClassifierpipelineRun) && ...
                        ~isempty(app.Data.ClassifierpipelineRun{i})
                    runRoot = uitreenode(g3(i),'Text','Run','Tag','ClassifierpipelineRunRoot', ...
                        'UserData',i,'Icon',fullfile(pth,'pipeline_run.png'));
                    for k=1:numel(app.Data.ClassifierpipelineRun{i})
                        createClassifierPipelineRunTreeNode(runRoot, i, k, pth);
                    end
                end

                %                if numel(app.Data.Classifierrois{i})

                %                    for n=1:numel(app.Data.Classifierrois{i})
                %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                %                       cm=uicontextmenu(app.DetecDivUIFigure);
                %                     m = uimenu(cm,'Text','Open ROI...');
                %                     m.MenuSelectedFcn={@contextMenuROIFcn,[i,n],'Projectposrois'};
                %                     'ContextMenu',cm
                %          uitreenode(g3(i),'Text',app.Data.Projectclassirois{i}{n},'Tag','Classifierrois','UserData',[i,n],'Icon',fullfile(pth,'roi.png'));
                %                                       % disabled because too heavy with large projects
                %                     end
                %                end


            end

            try, expand(app.ProjectsNode); catch, end
            try, expand(app.IndependentClassifiersNode); catch, end
            if app.componentExists('PipelinesNode')
                try, expand(app.PipelinesNode); catch, end
            end

            function pNode = createPipelineTreeNode(parentNode, pipeIdx, pth)
                pNode = [];
                if pipeIdx > numel(app.Data.Pipeline) || isempty(app.Data.Pipeline{pipeIdx})
                    return;
                end

                cm=uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cm,'Text','Open pipeline...');
                m.MenuSelectedFcn={@contextMenuPipelineFcn,pipeIdx,'Pipeline'};
                m = uimenu(cm,'Text','Add module...');
                m.MenuSelectedFcn={@contextMenuAddPipelineModuleFcn,pipeIdx,'Pipeline'};
                m = uimenu(cm,'Text','Save pipeline');
                m.MenuSelectedFcn={@contextMenuSavePipelineFcn,pipeIdx,'Pipeline'};
                m = uimenu(cm,'Text','Create run...');
                m.MenuSelectedFcn={@contextMenuCreatePipelineRunFcn,pipeIdx,'Pipeline'};
                m = uimenu(cm,'Text','Open pipeline.json...');
                m.MenuSelectedFcn={@contextMenuOpenPipelineJsonFcn,pipeIdx,'Pipeline'};
                m = uimenu(cm,'Text','Close pipeline');
                m.MenuSelectedFcn={@contextMenuClosePipelineFcn,pipeIdx,'Pipeline'};
                m = uimenu(cm,'Text','Delete pipeline folder');
                m.MenuSelectedFcn={@contextMenuDeletePipelineFcn,pipeIdx,'Pipeline'};

                label = app.Data.Pipeline{pipeIdx};
                if isfield(app.Data,'PipelineDisplay') && pipeIdx <= numel(app.Data.PipelineDisplay) && ...
                        ~isempty(app.Data.PipelineDisplay{pipeIdx})
                    label = app.Data.PipelineDisplay{pipeIdx};
                end

                pNode=uitreenode(parentNode,'Text',label,'Tag','Pipeline','UserData',pipeIdx, ...
                    'ContextMenu',cm,'Icon',fullfile(pth,'pipeline.png'));

                if pipeIdx <= numel(app.Data.PipelineModules) && ~isempty(app.Data.PipelineModules{pipeIdx})
                    for k=1:numel(app.Data.PipelineModules{pipeIdx})
                        moduleType = '';
                        if pipeIdx <= numel(app.Data.PipelineModuleTypes) && ~isempty(app.Data.PipelineModuleTypes{pipeIdx}) && k <= numel(app.Data.PipelineModuleTypes{pipeIdx})
                            moduleType = app.Data.PipelineModuleTypes{pipeIdx}{k};
                        end
                        iconFile = getPipelineModuleIcon(moduleType);

                        uitreenode(pNode,'Text',app.Data.PipelineModules{pipeIdx}{k},'Tag','PipelineModule','UserData',[pipeIdx,k], ...
                            'Icon',fullfile(pth,iconFile));
                    end
                end
            end

            function runNode = createPipelineRunTreeNode(parentNode, projIdx, runIdx, pth)
                runNode = [];
                if projIdx > numel(app.Data.ProjectpipelineRun) || runIdx > numel(app.Data.ProjectpipelineRun{projIdx})
                    return;
                end

                cm=uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cm,'Text','Run locally...');
                m.MenuSelectedFcn={@contextMenuRunPipelineRunFcn,[projIdx,runIdx],'ProjectpipelineRun'};
                m = uimenu(cm,'Text','Run on hub...');
                m.MenuSelectedFcn={@contextMenuRunPipelineRunOnHubFcn,[projIdx,runIdx],'ProjectpipelineRun'};
                m = uimenu(cm,'Text','Refresh hub status');
                m.MenuSelectedFcn={@contextMenuRefreshPipelineRunHubStatusFcn,[projIdx,runIdx],'ProjectpipelineRun'};
                m = uimenu(cm,'Text','Cancel hub job');
                m.MenuSelectedFcn={@contextMenuCancelPipelineRunHubJobFcn,[projIdx,runIdx],'ProjectpipelineRun'};
                m = uimenu(cm,'Text','Review run...');
                m.MenuSelectedFcn = @(~,~)openProjectPipelineRunInspector(projIdx, runIdx);
                m = uimenu(cm,'Text','Edit run...');
                m.MenuSelectedFcn = @(~,~)openProjectPipelineRunEditor(projIdx, runIdx, true);
                m = uimenu(cm,'Text','New run from this...');
                m.MenuSelectedFcn={@contextMenuOpenPipelineRunFcn,[projIdx,runIdx],'ProjectpipelineRun'};
                m = uimenu(cm,'Text','Open run log');
                m.MenuSelectedFcn = @(~,~)openPipelineRunLog(app, projIdx, runIdx);
                m = uimenu(cm,'Text','Open run folder');
                m.MenuSelectedFcn = @(~,~)openPipelineRunFolder(app, projIdx, runIdx);
                m = uimenu(cm,'Text','Delete run');
                m.MenuSelectedFcn={@contextMenuDeletePipelineRunFcn,[projIdx,runIdx],'ProjectpipelineRun'};

                runNode=uitreenode(parentNode,'Text',app.Data.ProjectpipelineRun{projIdx}{runIdx},'Tag','ProjectpipelineRun', ...
                    'UserData',[projIdx,runIdx],'ContextMenu',cm,'Icon',fullfile(pth,'pipeline_run.png'));
            end

            function contextMenuRefreshProjectHubLockFcn(~,~,projIdx,~)
                try
                    shallowObj = evalin('base', app.Data.Project{projIdx});
                    summary = detecdiv_hub_project_lock_summary(shallowObj);
                    applyProjectHubLockSummary(shallowObj, summary);
                    refreshProjectInformationIfSelected(projIdx);
                    uialert(app.DetecDivUIFigure, ...
                        detecdiv_hub_format_project_lock_summary(summary), ...
                        'Hub project lock', 'Icon', 'info');
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, ...
                        'Cannot read Hub project lock', 'Icon', 'error');
                end
            end

            function contextMenuReleaseProjectHubLockFcn(~,~,projIdx,~)
                try
                    shallowObj = evalin('base', app.Data.Project{projIdx});
                    hub = detecdiv_hub_settings_get();
                    summary = detecdiv_hub_project_lock_summary(shallowObj, hub);
                    applyProjectHubLockSummary(shallowObj, summary);
                    refreshProjectInformationIfSelected(projIdx);

                    if summary.lock_count == 0
                        [shallowObj, access] = detecdiv_hub_prepare_project_open(shallowObj, 'Hub', hub);
                        refreshProjectInformationIfSelected(projIdx);
                        if access.editable
                            uialert(app.DetecDivUIFigure, ...
                                'No foreign lock was active. A local edit lease is now held by this MATLAB session.', ...
                                'Hub project editable', 'Icon', 'success');
                        else
                            uialert(app.DetecDivUIFigure, access.reason, ...
                                'Hub project remains read-only', 'Icon', 'warning');
                        end
                        return;
                    end

                    lockKinds = string({summary.locks.kind});
                    if any(strcmpi(lockKinds, 'server_job'))
                        msg = [detecdiv_hub_format_project_lock_summary(summary) newline newline ...
                            'A running Hub job cannot be force-released here. Cancel it from the Run Monitor, or wait for it to finish.'];
                        uialert(app.DetecDivUIFigure, msg, 'Hub job owns this project', 'Icon', 'warning');
                        return;
                    end

                    msg = [detecdiv_hub_format_project_lock_summary(summary) newline newline ...
                        'Release this lease and take a new edit lease on this computer?' newline ...
                        'Only continue if the listed DetecDiv session is closed or no longer writing. ' ...
                        'Forcing release while it is active can allow concurrent writes to the same project.'];
                    choice = uiconfirm(app.DetecDivUIFigure, msg, 'Release Hub project lock?', ...
                        'Options', {'Release and edit here','Cancel'}, ...
                        'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');
                    if ~strcmp(choice, 'Release and edit here')
                        return;
                    end

                    for iLock = 1:numel(summary.locks)
                        lock = summary.locks(iLock);
                        if strcmpi(lock.kind, 'client_edit_lease') && ~isempty(lock.id)
                            detecdiv_hub_release_project_lease(shallowObj, lock.id, hub);
                        end
                    end

                    remaining = detecdiv_hub_project_lock_summary(shallowObj, hub);
                    if remaining.lock_count > 0
                        applyProjectHubLockSummary(shallowObj, remaining);
                        error('detecdiv:HubLockStillActive', ...
                            'The Hub still reports an active lock after the release request.');
                    end

                    [shallowObj, access] = detecdiv_hub_prepare_project_open(shallowObj, 'Hub', hub);
                    refreshProjectInformationIfSelected(projIdx);
                    if ~access.editable
                        error('detecdiv:HubLeaseAcquireFailed', '%s', access.reason);
                    end
                    uialert(app.DetecDivUIFigure, ...
                        'The previous lock was released. This MATLAB session now owns the project edit lease.', ...
                        'Hub project unlocked', 'Icon', 'success');
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, ...
                        'Cannot release Hub project lock', 'Icon', 'error');
                end
            end

            function applyProjectHubLockSummary(shallowObj, summary)
                if ~isprop(shallowObj, 'runProfiles') || ~isstruct(shallowObj.runProfiles)
                    shallowObj.runProfiles = struct();
                end
                if ~isfield(shallowObj.runProfiles, 'hub') || ~isstruct(shallowObj.runProfiles.hub)
                    shallowObj.runProfiles.hub = struct();
                end
                hubState = shallowObj.runProfiles.hub;
                hubState.hub_project_id = summary.project_id;
                hubState.active_locks = summary.locks;
                hubState.checked_at = summary.checked_at;
                hubState.reason = summary.reason;
                if summary.lock_count > 0
                    ownLeaseId = '';
                    try
                        ownLeaseId = char(string(hubState.lease.id));
                    catch
                    end
                    activeIds = string({summary.locks.id});
                    ownsActiveLease = ~isempty(ownLeaseId) && any(strcmp(activeIds, string(ownLeaseId)));
                    hubState.read_only = ~ownsActiveLease;
                    if ownsActiveLease
                        hubState.mode = 'lease_active';
                    else
                        hubState.mode = 'read_only';
                    end
                else
                    hubState.read_only = true;
                    hubState.mode = 'unlocked';
                    if isfield(hubState, 'lease')
                        hubState = rmfield(hubState, 'lease');
                    end
                end
                shallowObj.runProfiles.hub = hubState;
            end

            function refreshProjectInformationIfSelected(projIdx)
                try
                    node = app.Tree.SelectedNodes;
                    if numel(node) == 1 && strcmp(node.Tag, 'Project') && isequal(node.UserData, projIdx)
                        TreeSelectionChanged(app, []);
                    end
                catch
                end
            end

            function runNode = createClassifierPipelineRunTreeNode(parentNode, classifierIdx, runIdx, pth)
                runNode = [];
                if ~isfield(app.Data,'ClassifierpipelineRun') || classifierIdx > numel(app.Data.ClassifierpipelineRun) || ...
                        runIdx > numel(app.Data.ClassifierpipelineRun{classifierIdx})
                    return;
                end

                cm=uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cm,'Text','Review run...');
                m.MenuSelectedFcn = @(~,~)openClassifierPipelineRunInspector(app, classifierIdx, runIdx);
                m = uimenu(cm,'Text','Edit run...');
                m.MenuSelectedFcn = @(~,~)openClassifierPipelineRunEditor(app, classifierIdx, runIdx);
                m = uimenu(cm,'Text','New run from this...');
                m.MenuSelectedFcn = @(~,~)openClassifierPipelineRunSeed(app, classifierIdx, runIdx);
                m = uimenu(cm,'Text','Open run log');
                m.MenuSelectedFcn = @(~,~)openClassifierPipelineRunLog(app, classifierIdx, runIdx);
                m = uimenu(cm,'Text','Open run folder');
                m.MenuSelectedFcn = @(~,~)openClassifierPipelineRunFolder(app, classifierIdx, runIdx);

                runNode=uitreenode(parentNode,'Text',app.Data.ClassifierpipelineRun{classifierIdx}{runIdx},'Tag','ClassifierpipelineRun', ...
                    'UserData',[classifierIdx,runIdx],'ContextMenu',cm,'Icon',fullfile(pth,'pipeline_run.png'));
            end

            function contextMenuDeleteProcessFcn(src,event,arg,str)

                %               %  str,arg
                cc=arg(1);
                %                 if strcmp(str,'Processor')
                %                      cc=arg(1);
                %                      clas=app.Data.Classifier{cc};
                %                      %clas=evalin('base',clas);
                %
                %                      evalin('base',['clear ' clas]);
                %
                %                 end

                if strcmp(str,'Projectprocess')

                    proj=app.Data.Project{cc(1)};
                    pos=arg(2);
                    shallowObj=evalin('base',proj);
                    clas=shallowObj.processing.processor(pos);
                    n=1:numel(shallowObj.processing.processor);
                    pix=setxor(n,pos);

                    aa=uiconfirm(app.DetecDivUIFigure,'Are you really sure you want to delete this processor?','Warning');
                    if strcmp(aa,'OK')
                        % if numel(pix)

                        rmdir(clas.path,'s')
                        shallowObj.processing.processor=shallowObj.processing.processor(pix);
                        %   else

                        %   end
                    else
                        return;
                    end
                end

                % classifierGUI(clas)
                %    i,k
                RefreshtreewindowMenuSelected(app)
            end


function contextMenuDuplicateProcessFcn(src,event,arg,str) %#ok<INUSD>
    if ~strcmp(str,'Projectprocess')
        return;
    end

    proj = app.Data.Project{arg(1)};
    pos  = arg(2);
    shallowObj = evalin('base', proj);

    % ---- purge invalid processors ----
    procList = shallowObj.processing.processor;
    if ~isempty(procList)
        valid = arrayfun(@(p) (~isa(p,'handle')) || isvalid(p), procList);
        if any(~valid)
            procList = procList(valid);
            shallowObj.processing.processor = procList;
        end
    end

    if isempty(procList) || pos > numel(procList)
        uialert(app.DetecDivUIFigure, ...
            'This processor no longer exists (invalid handle). Tree will refresh.', ...
            'Warning','Icon','warning');
        RefreshtreewindowMenuSelected(app);
        return;
    end

    srcProc = procList(pos);

    % ---- propose a default name ----
    defaultName = [srcProc.strid '_copy'];
    answer = inputdlg({'New processor name:'}, 'Duplicate processor', 1, {defaultName});
    if isempty(answer), return; end
    newName = strtrim(answer{1});
    if isempty(newName), return; end

    % ---- avoid duplicate names ----
    existing = arrayfun(@(p) p.strid, procList, 'UniformOutput', false);
    if any(strcmp(existing, newName))
        uialert(app.DetecDivUIFigure, ...
            ['A processor named "' newName '" already exists in this project.'], ...
            'Name conflict','Icon','warning');
        return;
    end

    % ---- create new processor in project ----
    shallowObj.addProcessor('name', newName);
    dstProc = shallowObj.processing.processor(end);

    % ---- copy settings ----
    try, dstProc.processFun  = srcProc.processFun;  end
    try, dstProc.processArg  = srcProc.processArg;  end
    try, dstProc.runProfiles = srcProc.runProfiles; end
    try, dstProc.description = srcProc.description; end
    try, dstProc.category    = srcProc.category;    end

    try
        processSave(dstProc);
    catch ME
        warning('Duplicate processor: save failed: %s', ME.message);
    end

    RefreshtreewindowMenuSelected(app);
end


            function contextMenuDeleteClassiFcn(src,event,arg,str)

               cc = arg(1);

    % ---------------------------------------------------------------------
    % Cas 1 : classifier indépendant dans la base
    % ---------------------------------------------------------------------
    if strcmp(str,'Classifier')
        clas = app.Data.Classifier{cc};
        evalin('base',['clear ' clas]);
    end

    % ---------------------------------------------------------------------
    % Cas 2 : classifier attaché à un projet (shallow)
    % ---------------------------------------------------------------------
    if strcmp(str,'Projectclassi')
        proj = app.Data.Project{cc(1)};
        pos  = arg(2);

        shallowObj = evalin('base',proj);
        clas       = shallowObj.processing.classification(pos);

        n   = 1:numel(shallowObj.processing.classification);
        pix = setxor(n,pos);

        % confirmation utilisateur
        aa = uiconfirm(app.DetecDivUIFigure, ...
            'Are you really sure you want to delete the classifier?', ...
            'Warning');

        if ~strcmp(aa,'OK')
            return;
        end

        % -----------------------------------------------------------------
        % � Suppression sécurisée du dossier associé à ce classifier
        % -----------------------------------------------------------------
        classiPath = clas.path;

        if ~isempty(classiPath) && ischar(classiPath) && exist(classiPath,'dir')
            try
                rmdir(classiPath, 's');
            catch ME
                warning(['Could not delete classifier folder at: ' classiPath ...
                         '  | Reason: ' ME.message]);
            end
        else
            % Optionnel : avertir si le dossier n’existe pas
            disp(['[Info] Classifier folder does not exist or is invalid: ' char(classiPath)]);
        end

        % Mise à jour du shallowObj
        shallowObj.processing.classification = ...
            shallowObj.processing.classification(pix);
    end

    % Rafraîchissement de l’arbre
    RefreshtreewindowMenuSelected(app)

            end

            function contextMenuClassiFcn(src,event,arg,str)

                %  str,arg
                cc=arg(1);
                if strcmp(str,'Classifier')
                    cc=arg(1);
                    clas=app.Data.Classifier{cc};
                    clas=evalin('base',clas);
                end
                if strcmp(str,'Projectclassi')
                    proj=app.Data.Project{cc(1)};
                    pos=arg(2);
                    shallowObj=evalin('base',proj);
                    clas=shallowObj.processing.classification(pos);
                end

                classifierGUI(clas)
                %    i,k
            end
            function contextMenuProcessFcn(src,event,arg,str)

    cc = arg(1);

    if strcmp(str,'Projectprocess')
        proj = app.Data.Project{cc(1)};
        pos  = arg(2);
        shallowObj = evalin('base', proj);
        proc = shallowObj.processing.processor(pos);

        processDataGUI(shallowObj, proc);
    end

end

            function contextMenuPipelineFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'Pipeline')
                    return;
                end
                pipe = getPipelineByIndex(arg(1));
                if isempty(pipe)
                    return;
                end
                try
                    app.openPipelineWithContext(pipe);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Pipeline2 error', 'Icon', 'error');
                end
            end

            function pNode = createRecentPipelineTreeNode(parentNode, pipelineJson, pth)
                pNode = [];
                if isempty(pipelineJson)
                    return;
                end
                label = app.pipelineTreeLabelFromJson(pipelineJson);

                cm=uicontextmenu(app.DetecDivUIFigure);
                m = uimenu(cm,'Text','Open pipeline...');
                m.MenuSelectedFcn = @(~,~)app.openRecentPipelineCallback(pipelineJson);
                pNode=uitreenode(parentNode,'Text',label,'Tag','PipelineRecent','UserData',pipelineJson, ...
                    'ContextMenu',cm,'Icon',fullfile(pth,'pipeline.png'));
            end

            function contextMenuOpenPipelineModuleFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'PipelineModule')
                    return;
                end
                if numel(arg) < 2
                    return;
                end
                app.openPipelineModuleByIndex(arg(1), arg(2));
            end

            function contextMenuOpenPipelineJsonFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'Pipeline')
                    return;
                end
                pipe = getPipelineByIndex(arg(1));
                if isempty(pipe)
                    return;
                end
                jsonFile = fullfile(pipe.path, 'pipeline.json');
                if ~isfile(jsonFile)
                    try
                        pipelineSave(pipe);
                    catch ME
                        uialert(app.DetecDivUIFigure, ME.message, 'Error', 'Icon', 'error');
                        return;
                    end
                end
                edit(jsonFile);
            end

            function contextMenuSavePipelineFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'Pipeline')
                    return;
                end
                pipe = getPipelineByIndex(arg(1));
                if isempty(pipe)
                    return;
                end
                try
                    pipelineSave(pipe);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Error', 'Icon', 'error');
                end
            end

            function contextMenuClosePipelineFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'Pipeline')
                    return;
                end
                idx = arg(1);
                if idx > numel(app.Data.Pipeline)
                    return;
                end
                varName = app.Data.Pipeline{idx};
                evalin('base', ['clear ' varName]);
                RefreshtreewindowMenuSelected(app);
            end

            function contextMenuDeletePipelineFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'Pipeline')
                    return;
                end
                idx = arg(1);
                pipe = getPipelineByIndex(idx);
                if isempty(pipe)
                    return;
                end
                choice = uiconfirm(app.DetecDivUIFigure, ...
                    'Delete this pipeline folder from disk? This cannot be undone.', ...
                    'Warning');
                if ~strcmp(choice,'OK')
                    return;
                end

                pipePath = pipe.path;
                try
                    if ~isempty(pipePath) && isfolder(pipePath)
                        rmdir(pipePath, 's');
                    end
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Delete error', 'Icon', 'error');
                    return;
                end

                if idx <= numel(app.Data.Pipeline)
                    evalin('base', ['clear ' app.Data.Pipeline{idx}]);
                end
                RefreshtreewindowMenuSelected(app);
            end

            function contextMenuAddPipelineModuleFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'Pipeline')
                    return;
                end
                pipe = getPipelineByIndex(arg(1));
                if isempty(pipe)
                    return;
                end

                [ok, typeName, pkgName, nodeName] = askPipelineModuleSpec();
                if ~ok
                    return;
                end

                node = buildPipelineNode(typeName, pkgName, nodeName, pipe);
                if isempty(node)
                    uialert(app.DetecDivUIFigure, 'Unknown module type.', 'Error', 'Icon','error');
                    return;
                end

                if isempty(pipe.nodes)
                    pipe.nodes = node;
                else
                    pipe.nodes(end+1) = node;
                end

                try
                    pipelineSave(pipe);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Save error', 'Icon', 'error');
                    return;
                end

                RefreshtreewindowMenuSelected(app);
            end

            function contextMenuDeletePipelineModuleFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'PipelineModule')
                    return;
                end
                pipe = getPipelineByIndex(arg(1));
                modIdx = arg(2);
                if isempty(pipe) || modIdx > numel(pipe.nodes)
                    return;
                end

                node = pipe.nodes(modIdx);
                nodeId = getStructFieldText(node, 'id', ['node_' num2str(modIdx)]);
                choice = uiconfirm(app.DetecDivUIFigure, ...
                    ['Delete module ' nodeId ' from pipeline?'], 'Warning');
                if ~strcmp(choice,'OK')
                    return;
                end

                pipe.nodes(modIdx) = [];
                if isprop(pipe,'edges') && ~isempty(pipe.edges)
                    keep = true(1, numel(pipe.edges));
                    for e = 1:numel(pipe.edges)
                        fromVal = getStructFieldText(pipe.edges(e), 'from', '');
                        toVal   = getStructFieldText(pipe.edges(e), 'to', '');

                        if strcmp(fromVal, nodeId) || strcmp(toVal, nodeId)
                            keep(e) = false;
                            continue;
                        end

                        if isfield(pipe.edges(e),'from') && isnumeric(pipe.edges(e).from)
                            if pipe.edges(e).from == modIdx
                                keep(e) = false;
                            elseif pipe.edges(e).from > modIdx
                                pipe.edges(e).from = pipe.edges(e).from - 1;
                            end
                        end
                        if isfield(pipe.edges(e),'to') && isnumeric(pipe.edges(e).to)
                            if pipe.edges(e).to == modIdx
                                keep(e) = false;
                            elseif pipe.edges(e).to > modIdx
                                pipe.edges(e).to = pipe.edges(e).to - 1;
                            end
                        end
                    end
                    pipe.edges = pipe.edges(keep);
                end

                cleanupPipelineNodeDiskArtifacts(pipe, node);
                try
                    pipelineSave(pipe);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Save error', 'Icon', 'error');
                    return;
                end

                RefreshtreewindowMenuSelected(app);
            end

            function cleanupPipelineNodeDiskArtifacts(pipeObj, nodeStruct)
                if isempty(pipeObj) || ~isa(pipeObj, 'pipeline') || isempty(pipeObj.path) || ~isfolder(pipeObj.path)
                    return;
                end

                nodeIdLocal = getStructFieldText(nodeStruct, 'id', '');
                if ~isempty(nodeIdLocal)
                    tryDeletePipelineOwnedPath(fullfile(pipeObj.path, 'modules', sanitizePipelineOwnedName(nodeIdLocal)), pipeObj.path);
                end

                paramsLocal = getStructField(nodeStruct, 'params', struct());
                if isstruct(paramsLocal) && isfield(paramsLocal, 'modulePath') && ~isempty(paramsLocal.modulePath)
                    tryDeletePipelineOwnedPath(resolvePipelineOwnedPath(paramsLocal.modulePath, pipeObj.path), pipeObj.path);
                end
                if isfield(nodeStruct, 'origin') && isstruct(nodeStruct.origin) && isfield(nodeStruct.origin, 'path') && ~isempty(nodeStruct.origin.path)
                    tryDeletePipelineOwnedPath(resolvePipelineOwnedPath(nodeStruct.origin.path, pipeObj.path), pipeObj.path);
                end

                try
                    modulesRoot = fullfile(pipeObj.path, 'modules');
                    if isfolder(modulesRoot)
                        d = dir(modulesRoot);
                        d = d(~ismember({d.name}, {'.','..'}));
                        if isempty(d)
                            rmdir(modulesRoot, 's');
                        end
                    end
                catch
                end
            end

            function out = resolvePipelineOwnedPath(p, pipeRoot)
                out = char(string(p));
                if isempty(out)
                    return;
                end
                if isAbsolutePipelineOwnedPath(out)
                    return;
                end
                if ~isempty(pipeRoot) && isfolder(pipeRoot)
                    out = fullfile(pipeRoot, out);
                end
            end

            function tf = isAbsolutePipelineOwnedPath(p)
                tf = false;
                if isempty(p)
                    return;
                end
                p = char(string(p));
                if ispc
                    tf = ~isempty(regexp(p, '^[A-Za-z]:[\\/]', 'once')) || startsWith(p, '\\');
                else
                    tf = startsWith(p, '/');
                end
            end

            function tf = isPipelineOwnedSubPath(childPath, parentPath)
                tf = false;
                childPath = lower(strrep(char(string(childPath)), '\', '/'));
                parentPath = lower(strrep(char(string(parentPath)), '\', '/'));
                childPath = regexprep(childPath, '/+$', '');
                parentPath = regexprep(parentPath, '/+$', '');
                if isempty(childPath) || isempty(parentPath)
                    return;
                end
                if strcmpi(childPath, parentPath)
                    tf = true;
                    return;
                end
                if ~endsWith(parentPath, '/')
                    parentPath = [parentPath '/'];
                end
                tf = startsWith(childPath, parentPath, 'IgnoreCase', true);
            end

            function tryDeletePipelineOwnedPath(targetPath, pipeRoot)
                if isempty(targetPath)
                    return;
                end
                targetPath = char(string(targetPath));
                if ~isPipelineOwnedSubPath(targetPath, pipeRoot)
                    return;
                end
                try
                    if isfolder(targetPath)
                        rmdir(targetPath, 's');
                    elseif exist(targetPath, 'file') == 2
                        delete(targetPath);
                    end
                catch
                end
            end

            function out = sanitizePipelineOwnedName(nameIn)
                out = regexprep(char(string(nameIn)), '[^a-zA-Z0-9_\-]', '_');
                if isempty(out)
                    out = 'node';
                end
            end

            function contextMenuCreatePipelineRunFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'Pipeline')
                    return;
                end
                pipe = getPipelineByIndex(arg(1));
                if isempty(pipe)
                    return;
                end
                try
                    pipelineRunGUI(pipe);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Run GUI error', 'Icon', 'error');
                end
            end

            function contextMenuOpenPipelineRunFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'ProjectpipelineRun')
                    return;
                end
                runObj = getProjectRunByIndex(arg(1), arg(2));
                if isempty(runObj)
                    return;
                end
                openProjectPipelineRunEditor(arg(1), arg(2));
            end

            function contextMenuDeletePipelineRunFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'ProjectpipelineRun')
                    return;
                end
                projIdx = arg(1);
                runIdx = arg(2);
                if projIdx > numel(app.Data.Project)
                    return;
                end

                projVar = app.Data.Project{projIdx};
                shallowObj = evalin('base', projVar);
                if ~isfield(shallowObj.processing,'pipelineRun') || runIdx > numel(shallowObj.processing.pipelineRun)
                    return;
                end

                runObj = shallowObj.processing.pipelineRun(runIdx);
                choice = uiconfirm(app.DetecDivUIFigure, ...
                    ['Delete run ' runObj.runId '?'], 'Warning');
                if ~strcmp(choice,'OK')
                    return;
                end

                try
                    if ~isempty(runObj.path) && isfolder(runObj.path)
                        rmdir(runObj.path, 's');
                    end
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Delete error', 'Icon', 'error');
                    return;
                end

                keep = setxor(1:numel(shallowObj.processing.pipelineRun), runIdx);
                if isempty(keep)
                    shallowObj.processing.pipelineRun = pipelineRun.empty;
                else
                    shallowObj.processing.pipelineRun = shallowObj.processing.pipelineRun(keep);
                end
                assignin('base', projVar, shallowObj);
                try
                    shallowSave(shallowObj);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Project save error', 'Icon', 'warning');
                end
                RefreshtreewindowMenuSelected(app);
            end

            function contextMenuDeleteAllPipelineRunsFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'ProjectpipelineRunRoot')
                    return;
                end
                deleteAllProjectPipelineRuns(arg);
            end

            function contextMenuRunPipelineRunFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'ProjectpipelineRun')
                    return;
                end
                executeProjectPipelineRun(arg(1), arg(2));
            end

            function contextMenuRunPipelineRunOnHubFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'ProjectpipelineRun')
                    return;
                end
                submitProjectPipelineRunToHub(arg(1), arg(2));
            end

            function contextMenuRefreshPipelineRunHubStatusFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'ProjectpipelineRun')
                    return;
                end
                refreshProjectPipelineRunHubStatus(arg(1), arg(2), true);
            end

            function contextMenuCancelPipelineRunHubJobFcn(src,event,arg,str) %#ok<INUSD>
                if ~strcmp(str,'ProjectpipelineRun')
                    return;
                end
                cancelProjectPipelineRunHubJob(arg(1), arg(2));
            end

            function deleteAllProjectPipelineRuns(projIdx)
                if projIdx > numel(app.Data.Project)
                    return;
                end

                projVar = app.Data.Project{projIdx};
                shallowObj = evalin('base', projVar);
                if ~isfield(shallowObj.processing,'pipelineRun') || isempty(shallowObj.processing.pipelineRun)
                    return;
                end

                nRuns = numel(shallowObj.processing.pipelineRun);
                choice = uiconfirm(app.DetecDivUIFigure, ...
                    sprintf('Delete all %d pipeline run(s) from this project and disk?', nRuns), ...
                    'Delete all runs', ...
                    'Options', {'Delete all','Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');
                if ~strcmp(choice,'Delete all')
                    return;
                end

                runPaths = strings(0,1);
                for runIdx = 1:nRuns
                    runObj = shallowObj.processing.pipelineRun(runIdx);
                    hasPath = (isobject(runObj) && isprop(runObj,'path')) || (isstruct(runObj) && isfield(runObj,'path'));
                    if hasPath && ~isempty(runObj.path)
                        runPaths(end+1,1) = string(runObj.path); %#ok<AGROW>
                    end
                end
                runPaths = unique(runPaths(runPaths ~= ""), 'stable');

                for pathIdx = 1:numel(runPaths)
                    runPath = char(runPaths(pathIdx));
                    try
                        if isfolder(runPath)
                            rmdir(runPath, 's');
                        end
                    catch ME
                        uialert(app.DetecDivUIFigure, ...
                            sprintf('Could not delete run folder:\n%s\n\n%s', runPath, ME.message), ...
                            'Delete error', 'Icon', 'error');
                        return;
                    end
                end

                shallowObj.processing.pipelineRun = pipelineRun.empty;
                assignin('base', projVar, shallowObj);
                try
                    shallowSave(shallowObj);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Project save error', 'Icon', 'warning');
                end
                RefreshtreewindowMenuSelected(app);
            end

            function pipe = getPipelineByIndex(idx)
                pipe = [];
                if idx > numel(app.Data.Pipeline)
                    return;
                end
                varName = app.Data.Pipeline{idx};
                try
                    pipe = evalin('base', varName);
                catch
                    pipe = [];
                end
                if ~isa(pipe,'pipeline')
                    pipe = [];
                end
            end

            function label = formatPipelineRunLabel(runObj, fallbackIdx)
                label = '';
                try
                    if isprop(runObj,'runId') && ~isempty(runObj.runId)
                        label = char(string(runObj.runId));
                    end
                catch
                end
                if isempty(label)
                    label = ['run_' num2str(fallbackIdx)];
                end
            end

            function [runMode, runStatus] = summarizePipelineRun(runObj)
                runMode = 'local';
                runStatus = 'unknown';

                try
                    if isprop(runObj,'status') && ~isempty(runObj.status)
                        runStatus = char(string(runObj.status));
                    end
                catch
                end

                try
                    if isstruct(runObj.ctx) && isfield(runObj.ctx,'hub') && isstruct(runObj.ctx.hub)
                        runMode = 'hub';
                        if strcmp(runStatus, 'unknown') && isfield(runObj.ctx.hub,'status') && ~isempty(runObj.ctx.hub.status)
                            runStatus = char(string(runObj.ctx.hub.status));
                        end
                    end
                catch
                end

                if startsWith(runStatus,'hub_')
                    runMode = 'hub';
                    runStatus = extractAfter(runStatus, 4);
                    if isempty(runStatus)
                        runStatus = 'unknown';
                    end
                elseif any(strcmpi(runStatus, {'new','running','done','failed','cancelled'}))
                    if strcmp(runMode, 'local')
                        runMode = 'local';
                    end
                elseif isempty(runStatus)
                    runStatus = 'unknown';
                end
            end

            function [ok,node,pipeObj] = getPipelineNodeByIndex(pipeIdx, modIdx)
                ok = false;
                node = struct();
                pipeObj = [];

                pipeObj = getPipelineByIndex(pipeIdx);
                if isempty(pipeObj) || ~isprop(pipeObj,'nodes') || modIdx > numel(pipeObj.nodes)
                    return;
                end

                node = pipeObj.nodes(modIdx);
                ok = true;
            end

            function [nType, modObj] = buildPipelineModuleObject(node)
                nType = '';
                modObj = [];

                if ~(isstruct(node) && isfield(node,'type') && ~isempty(node.type))
                    return;
                end

                nType = lower(char(string(node.type)));

                if strcmp(nType,'processor')
                    tmpProc = process(tempdir, 'pipeline_module', randi(1e9));

                    pkgName = '';
                    if isfield(node,'pkg') && ~isempty(node.pkg)
                        pkgName = char(string(node.pkg));
                    end

                    if ~isempty(pkgName)
                        tmpProc.processFun = [pkgName '.process'];
                        try
                            p0 = feval([pkgName '.setparam'], struct());
                        catch
                            p0 = struct();
                        end
                        if isstruct(p0)
                            tmpProc.processArg = p0;
                        end
                    elseif isfield(node,'func') && ~isempty(node.func)
                        tmpProc.processFun = char(string(node.func));
                    end

                    if isfield(node,'params') && isstruct(node.params)
                        if isempty(tmpProc.processArg) || ~isstruct(tmpProc.processArg)
                            tmpProc.processArg = node.params;
                        else
                            fn = fieldnames(node.params);
                            for fi = 1:numel(fn)
                                tmpProc.processArg.(fn{fi}) = node.params.(fn{fi});
                            end
                        end
                    end

                    if isfield(node,'id') && ~isempty(node.id)
                        tmpProc.strid = char(string(node.id));
                    end

                    modObj = tmpProc;
                    return;
                end

                if strcmp(nType,'classifier')
                    tmpClassi = classi(tempdir, 'pipeline_module', randi(1e9));

                    if isfield(node,'id') && ~isempty(node.id)
                        tmpClassi.strid = char(string(node.id));
                    end

                    pkgName = '';
                    if isfield(node,'pkg') && ~isempty(node.pkg)
                        pkgName = char(string(node.pkg));
                    end

                    if ~isempty(pkgName)
                        tmpClassi.classifierPkg = pkgName;
                        if isempty(tmpClassi.classifyFun)
                            tmpClassi.classifyFun = [pkgName '.classify'];
                        end
                        if isempty(tmpClassi.trainingFun)
                            tmpClassi.trainingFun = [pkgName '.train'];
                        end

                        tmpClassi.category = {'Image'};
                        try
                            spec = feval([pkgName '.executionSpec'], tmpClassi);
                            if isstruct(spec) && isfield(spec,'category') && ~isempty(spec.category)
                                tmpClassi.category = classiNormalizeCategory(spec.category);
                            elseif strcmpi(pkgName,'cnn_lstm')
                                tmpClassi.category = {'LSTM'};
                            end
                        catch
                            if strcmpi(pkgName,'cnn_lstm')
                                tmpClassi.category = {'LSTM'};
                            end
                        end
                    else
                        tmpClassi.category = {'Image'};
                    end

                    if isfield(node,'func') && ~isempty(node.func)
                        tmpClassi.classifyFun = char(string(node.func));
                    end

                    if isfield(node,'params') && isstruct(node.params)
                        if isfield(node.params,'classes') && ~isempty(node.params.classes)
                            cls = node.params.classes;
                            if isstring(cls), cls = cellstr(cls); end
                            if ischar(cls), cls = {cls}; end
                            tmpClassi.classes = cls;
                        end
                    end

                    tmpClassi.category = classiNormalizeCategory(tmpClassi.category);
                    modObj = tmpClassi;
                    return;
                end
            end

            function openPipelineModuleByIndex(pipeIdx, modIdx)
                [ok,node,pipeObj] = app.getPipelineNodeByIndex(pipeIdx, modIdx);
                if ~ok
                    return;
                end

                [nType, modObj] = buildPipelineModuleObject(node);
                try
                    switch nType
                        case 'processor'
                            processDataGUI([], modObj);
                        case 'classifier'
                            classifierGUI(modObj);
                        otherwise
                            app.openPipelineWithContext(pipeObj);
                    end
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Module GUI error', 'Icon', 'error');
                end
            end

            function runObj = getProjectRunByIndex(projIdx, runIdx)
                runObj = [];
                if projIdx > numel(app.Data.Project)
                    return;
                end
                projVar = app.Data.Project{projIdx};
                shallowObj = evalin('base', projVar);
                if ~isfield(shallowObj.processing,'pipelineRun') || runIdx > numel(shallowObj.processing.pipelineRun)
                    return;
                end
                runObj = shallowObj.processing.pipelineRun(runIdx);
            end

            function shallowObj = getProjectByIndex(projIdx)
                shallowObj = [];
                if projIdx > numel(app.Data.Project)
                    return;
                end
                projVar = app.Data.Project{projIdx};
                try
                    shallowObj = evalin('base', projVar);
                catch
                    shallowObj = [];
                end
                if ~isa(shallowObj, 'shallow')
                    shallowObj = [];
                end
            end

            function openProjectPipelineRunInspector(projIdx, runIdx)
                shallowObj = getProjectByIndex(projIdx);
                runObj = getProjectRunByIndex(projIdx, runIdx);
                if isempty(runObj)
                    return;
                end
                try
                    pipelineRunInspector(runObj, shallowObj);
                catch ME
                    runJson = fullfile(runObj.path, 'run.json');
                    if isfile(runJson)
                        edit(runJson);
                    end
                    uialert(app.DetecDivUIFigure, ME.message, 'Pipeline run viewer error', 'Icon', 'warning');
                end
            end

            function openProjectPipelineRunEditor(projIdx, runIdx, editExisting)
                if nargin < 3
                    editExisting = false;
                end
                shallowObj = getProjectByIndex(projIdx);
                runObj = getProjectRunByIndex(projIdx, runIdx);
                if isempty(runObj)
                    return;
                end
                try
                    if logical(editExisting)
                        app.openPipelineEditorWithProgress(shallowObj, runObj, 'Opening run for editing...', 'EditExistingRun', true);
                    else
                        app.openPipelineEditorWithProgress(shallowObj, runObj, 'Opening pipeline run editor...');
                    end
                    RefreshtreewindowMenuSelected(app, []);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Pipeline run editor error', 'Icon', 'warning');
                end
            end

            function executeProjectPipelineRun(projIdx, runIdx)
                if projIdx > numel(app.Data.Project)
                    return;
                end

                projVar = app.Data.Project{projIdx};
                shallowObj = evalin('base', projVar);
                if ~isfield(shallowObj.processing,'pipelineRun') || runIdx > numel(shallowObj.processing.pipelineRun)
                    return;
                end

                runObj = shallowObj.processing.pipelineRun(runIdx);
                try
                    detecdiv_hub_assert_project_writable(shallowObj);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Project is read-only', 'Icon', 'warning');
                    return;
                end
                [pipeObj, msg] = resolvePipelineFromRun(runObj, shallowObj);
                if isempty(pipeObj)
                    uialert(app.DetecDivUIFigure, msg, 'Run error', 'Icon', 'error');
                    return;
                end
                runObj = backfillRunPipelineRef(runObj, pipeObj, shallowObj);
                shallowObj.processing.pipelineRun(runIdx) = runObj;

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...', ...
                    'Message',['Running pipeline run ' runObj.runId '...']);
                d.Value = 0.2;

                try
                    runObj.status = 'running';
                    pipelineRunSave(runObj);

                    ctx = runObj.ctx;
                    ctx.shallow = shallowObj;
                    ctx.shallowObj = shallowObj;
                    ctx.allowGUI = true;

                    [ctxOut, report] = app.runStructuredPipeline(pipeObj, ctx);

                    runObj.ctx = ctxOut;
                    runObj.outputs = struct('report', report);
                    runObj.status = 'done';
                    runObj.updatedAt = char(datetime('now'));
                    pipelineRunSave(runObj);

                    d.Value = 1;
                    d.Message = 'Pipeline run completed.';
                    pause(0.2);
                    close(d);
                catch ME
                    runObj.status = 'failed';
                    runObj.updatedAt = char(datetime('now'));
                    runObj.outputs = struct('error', struct( ...
                        'message', ME.message, ...
                        'identifier', ME.identifier));
                    try
                        lastReport = getappdata(0, 'DetecDivLastPipelineReport');
                        if ~isempty(lastReport)
                            runObj.outputs.report = lastReport;
                        end
                    catch
                    end
                    try
                        pipelineRunSave(runObj);
                    catch
                    end
                    close(d);
                    uialert(app.DetecDivUIFigure, ME.message, 'Run failed', 'Icon', 'error');
                end

                RefreshtreewindowMenuSelected(app);
            end

            function submitProjectPipelineRunToHub(projIdx, runIdx)
                if projIdx > numel(app.Data.Project)
                    return;
                end
                projVar = app.Data.Project{projIdx};
                shallowObj = evalin('base', projVar);
                if ~isfield(shallowObj.processing,'pipelineRun') || runIdx > numel(shallowObj.processing.pipelineRun)
                    return;
                end
                runObj = shallowObj.processing.pipelineRun(runIdx);
                choice = uiconfirm(app.DetecDivUIFigure, ...
                    {'Submit this existing pipeline run to the hub?', ...
                     'The local project will be considered stale once the hub job writes results.'}, ...
                    'Run on hub', 'Options', {'Submit','Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'warning');
                if ~strcmp(choice, 'Submit')
                    return;
                end
                d = uiprogressdlg(app.DetecDivUIFigure, 'Title', 'Please Wait...', ...
                    'Message', ['Submitting hub job for ' runObj.runId '...'], 'Indeterminate', 'on');
                try
                    try
                        detecdiv_hub_release_project_open(shallowObj);
                    catch
                    end
                    job = detecdiv_hub_submit_pipeline_run(runObj, shallowObj);
                    shallowObj.processing.pipelineRun(runIdx) = runObj;
                    if isprop(shallowObj, 'runProfiles')
                        if ~isfield(shallowObj.runProfiles, 'hub') || ~isstruct(shallowObj.runProfiles.hub)
                            shallowObj.runProfiles.hub = struct();
                        end
                        shallowObj.runProfiles.hub.read_only = true;
                        shallowObj.runProfiles.hub.reason = 'Hub pipeline job submitted; reload project before further local editing.';
                    end
                    assignin('base', projVar, shallowObj);
                    close(d);
                    uialert(app.DetecDivUIFigure, ...
                        sprintf('Hub job submitted: %s\nStatus: %s\nReload the project after the job completes before editing locally.', ...
                        char(string(job.id)), char(string(job.status))), ...
                        'Hub job submitted', 'Icon', 'success');
                catch ME
                    close(d);
                    uialert(app.DetecDivUIFigure, ME.message, 'Hub submit failed', 'Icon', 'error');
                end
                RefreshtreewindowMenuSelected(app);
            end

            function refreshProjectPipelineRunHubStatus(projIdx, runIdx, showDialog)
                if nargin < 4
                    showDialog = false;
                end
                if projIdx > numel(app.Data.Project)
                    return;
                end
                projVar = app.Data.Project{projIdx};
                shallowObj = evalin('base', projVar);
                runObj = shallowObj.processing.pipelineRun(runIdx);
                jobId = localRunHubJobId(runObj);
                if isempty(jobId)
                    if showDialog
                        uialert(app.DetecDivUIFigure, 'This run has no hub_job_id.', 'Hub status', 'Icon', 'warning');
                    end
                    return;
                end
                try
                    job = detecdiv_hub_get_pipeline_run(jobId);
                    if ~isstruct(runObj.ctx), runObj.ctx = struct(); end
                    if ~isfield(runObj.ctx, 'hub') || ~isstruct(runObj.ctx.hub), runObj.ctx.hub = struct(); end
                    runObj.ctx.hub.job_id = char(string(job.id));
                    runObj.ctx.hub.status = char(string(job.status));
                    runObj.ctx.hub.refreshed_at = char(datetime('now'));
                    runObj.status = ['hub_' char(string(job.status))];
                    shallowObj.processing.pipelineRun(runIdx) = runObj;
                    assignin('base', projVar, shallowObj);
                    pipelineRunSave(runObj);
                    reloadMsg = '';
                    if any(strcmp(char(string(job.status)), {'done','failed','cancelled'}))
                        reloadDialog = uiprogressdlg(app.DetecDivUIFigure, ...
                            'Title', 'Refreshing Hub project', ...
                            'Message', 'Reloading project results and acquiring a local edit lease...', ...
                            'Indeterminate', 'on');
                        drawnow;
                        [~, reloadMsg] = localReloadProjectFromDiskAfterHubTerminalStatus(projVar, shallowObj, runObj, runIdx);
                        try
                            close(reloadDialog);
                        catch
                        end
                    end
                    if showDialog
                        msg = sprintf('Hub job: %s\nStatus: %s', char(string(job.id)), char(string(job.status)));
                        if any(strcmp(char(string(job.status)), {'done','failed','cancelled'}))
                            if isempty(reloadMsg)
                                reloadMsg = 'Project changed on hub/server.';
                            end
                            msg = sprintf('%s\n\n%s', msg, reloadMsg);
                        end
                        uialert(app.DetecDivUIFigure, msg, 'Hub status', 'Icon', 'info');
                    end
                catch ME
                    if showDialog
                        uialert(app.DetecDivUIFigure, ME.message, 'Hub status failed', 'Icon', 'error');
                    end
                end
            end

            function cancelProjectPipelineRunHubJob(projIdx, runIdx)
                runObj = getProjectRunByIndex(projIdx, runIdx);
                jobId = localRunHubJobId(runObj);
                if isempty(jobId)
                    uialert(app.DetecDivUIFigure, 'This run has no hub_job_id.', 'Cancel hub job', 'Icon', 'warning');
                    return;
                end
                choice = uiconfirm(app.DetecDivUIFigure, ['Cancel hub job ' jobId '?'], ...
                    'Cancel hub job', 'Options', {'Cancel job','Keep running'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');
                if ~strcmp(choice, 'Cancel job')
                    return;
                end
                try
                    job = detecdiv_hub_cancel_pipeline_run(jobId);
                    refreshProjectPipelineRunHubStatus(projIdx, runIdx, false);
                    uialert(app.DetecDivUIFigure, ...
                        sprintf('Cancellation requested.\nHub status: %s', char(string(job.status))), ...
                        'Cancel hub job', 'Icon', 'info');
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Cancel hub job failed', 'Icon', 'error');
                end
            end

            function jobId = localRunHubJobId(runObj)
                jobId = '';
                try
                    if isstruct(runObj.ctx) && isfield(runObj.ctx, 'hub') && isstruct(runObj.ctx.hub)
                        if isfield(runObj.ctx.hub, 'job_id') && ~isempty(runObj.ctx.hub.job_id)
                            jobId = char(string(runObj.ctx.hub.job_id));
                        elseif isfield(runObj.ctx.hub, 'hub_job_id') && ~isempty(runObj.ctx.hub.hub_job_id)
                            jobId = char(string(runObj.ctx.hub.hub_job_id));
                        end
                    end
                    if isempty(jobId) && isstruct(runObj.ctx) && isfield(runObj.ctx, 'run') && isstruct(runObj.ctx.run) && ...
                            isfield(runObj.ctx.run, 'control') && isstruct(runObj.ctx.run.control) && ...
                            isfield(runObj.ctx.run.control, 'jobId') && ~isempty(runObj.ctx.run.control.jobId)
                        jobId = char(string(runObj.ctx.run.control.jobId));
                    end
                catch
                end
            end

            function [ok, msg] = localReloadProjectFromDiskAfterHubTerminalStatus(projVar, shallowObj, runObj, runIdx)
                ok = false;
                msg = '';
                projectMatPath = localProjectMatPath(shallowObj);
                if isempty(projectMatPath) || exist(projectMatPath, 'file') ~= 2
                    msg = 'Project changed on hub/server. Reload before local editing.';
                    return;
                end
                try
                    [reloadedObj, hubAccess, resumeInfo] = detecdiv_hub_resume_project_editing( ...
                        shallowObj, 'ProjectMatPath', projectMatPath);
                    try
                        if isfield(reloadedObj.processing, 'pipelineRun') ...
                                && runIdx >= 1 && runIdx <= numel(reloadedObj.processing.pipelineRun)
                            reloadedObj.processing.pipelineRun(runIdx) = runObj;
                        end
                    catch
                    end
                    assignin('base', projVar, reloadedObj);
                    try
                        app.autoLoadPipelinesForProjectRuns(reloadedObj);
                    catch
                    end
                    try
                        gatherVarsFromWorkspace(app);
                        displayNodes(app);
                    catch
                    end
                    ok = true;
                    msg = resumeInfo.message;
                    if hubAccess.readOnly
                        ok = false;
                    end
                catch ME
                    msg = ['Project changed on hub/server, but automatic reload failed. ' ...
                        'Reload before local editing. ' ME.message];
                end
            end

            function projectMatPath = localProjectMatPath(projectObj)
                projectMatPath = '';
                try
                    if isempty(projectObj) || ~isa(projectObj, 'shallow')
                        return;
                    end
                    if isempty(projectObj.io.path) || isempty(projectObj.io.file)
                        return;
                    end
                    projectMatPath = fullfile(char(string(projectObj.io.path)), [char(string(projectObj.io.file)) '.mat']);
                catch
                    projectMatPath = '';
                end
            end

            function [pipeObj, msg] = resolvePipelineFromRun(runObj, shallowObj)
                pipeObj = [];
                msg = 'Could not resolve pipeline for this run.';

                try
                    spec = runObj.ctx.pipelineSpec;
                    if isstruct(spec) && isfield(spec,'nodes') && ~isempty(spec.nodes)
                        pipeObj = spec;
                        if ~isfield(pipeObj,'edges') || isempty(pipeObj.edges)
                            pipeObj.edges = struct([]);
                        end
                        msg = '';
                        return;
                    end
                catch
                end

                if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef)
                    if isfield(runObj.pipelineRef,'path') && ~isempty(runObj.pipelineRef.path)
                        [pipeObj, m] = pipelineLoad(runObj.pipelineRef.path);
                        if ~isempty(pipeObj)
                            msg = '';
                            return;
                        end
                        if ~isempty(m)
                            msg = m;
                        end
                    end
                end

                if isprop(runObj,'templatePath') && ~isempty(runObj.templatePath)
                    [pipeObj, m] = pipelineLoad(runObj.templatePath);
                    if ~isempty(pipeObj)
                        msg = '';
                        return;
                    end
                    if ~isempty(m)
                        msg = m;
                    end
                end

                if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef) && isfield(runObj.pipelineRef,'id') ...
                        && ~isempty(runObj.pipelineRef.id)
                    vars = evalin('base','who');
                    for vi = 1:numel(vars)
                        try
                            tmp = evalin('base', vars{vi});
                        catch
                            continue;
                        end
                        if isa(tmp,'pipeline') && strcmp(tmp.strid, char(string(runObj.pipelineRef.id)))
                            pipeObj = tmp;
                            msg = '';
                            return;
                        end
                    end
                end

                try
                    [foundDefault, defaultPipe] = app.getProjectDefaultPipelineObject(shallowObj);
                catch
                    foundDefault = false;
                    defaultPipe = [];
                end
                if foundDefault && ~isempty(defaultPipe)
                    wantId = '';
                    try
                        wantId = char(string(runObj.pipelineRef.id));
                    catch
                    end
                    if isempty(wantId) || strcmp(defaultPipe.strid, wantId)
                        pipeObj = defaultPipe;
                        msg = '';
                        return;
                    end
                end

            end

            function runObj = backfillRunPipelineRef(runObj, pipeObj, shallowObj)
                if nargin < 1 || isempty(runObj)
                    return;
                end
                resolvedPath = '';
                try
                    if isa(pipeObj,'pipeline') && isprop(pipeObj,'path') && ~isempty(pipeObj.path)
                        resolvedPath = char(string(pipeObj.path));
                    end
                catch
                end

                if isempty(resolvedPath)
                    try
                        defaultJson = app.getProjectDefaultPipelinePath(shallowObj);
                        if ~isempty(defaultJson)
                            resolvedPath = fileparts(defaultJson);
                        end
                    catch
                    end
                end

                if isempty(resolvedPath)
                    return;
                end

                if ~isprop(runObj,'pipelineRef') || isempty(runObj.pipelineRef) || ~isstruct(runObj.pipelineRef)
                    runObj.pipelineRef = struct('id','','path','','version','');
                end
                runObj.pipelineRef.path = resolvedPath;
                if isprop(runObj,'templatePath')
                    runObj.templatePath = resolvedPath;
                end
                if isprop(runObj,'templateId') && isempty(runObj.templateId) && isfield(runObj.pipelineRef,'id')
                    runObj.templateId = runObj.pipelineRef.id;
                end
                if isstruct(runObj.ctx)
                    if ~isfield(runObj.ctx,'pipelineRef') || ~isstruct(runObj.ctx.pipelineRef)
                        runObj.ctx.pipelineRef = runObj.pipelineRef;
                    else
                        runObj.ctx.pipelineRef.path = resolvedPath;
                    end
                end
            end

            function node = buildPipelineNode(typeIn, pkgName, nodeName, pipeObj)
                node = [];
                t = lower(strtrim(char(string(typeIn))));
                t = strrep(t, ' ', '');
                t = strrep(t, '_', '');
                switch t
                    case {'dataloader'}
                        t = 'dataloader';
                    case {'roipattern','roiidentification','roiidentify'}
                        if strcmp(t, 'roipattern')
                            t = 'roipattern';
                        else
                            t = 'roiidentify';
                        end
                    case {'roimanual'}
                        t = 'roimanual';
                    case {'roigrid'}
                        t = 'roigrid';
                    case {'roiextraction','roiextract'}
                        t = 'roiextract';
                    case {'processor','classifier'}
                        % keep as is
                    otherwise
                        % keep original token to fail in switch below
                end

                switch t
                    case 'dataloader'
                        typeName = 'dataLoader';
                        funcName = 'dataLoader.process';
                        inNames = {};
                        outNames = {'images'};
                        req = {'path'};
                        try
                            params = dataLoader.setparam(struct());
                        catch
                            params = struct();
                        end
                    case 'roipattern'
                        typeName = 'roiPattern';
                        funcName = 'roiPattern.process';
                        inNames = {'images'};
                        outNames = {'roiList'};
                        req = {};
                        try
                            params = roiPattern.setparam(struct());
                        catch
                            params = struct();
                        end
                    case 'roiidentify'
                        typeName = 'roiIdentify';
                        funcName = 'roiIdentify.process';
                        inNames = {'images'};
                        outNames = {'roiList'};
                        req = {};
                        try
                            params = roiIdentify.setparam(struct());
                        catch
                            params = struct();
                        end
                    case 'roimanual'
                        typeName = 'roiManual';
                        funcName = 'roiManual.process';
                        inNames = {'images'};
                        outNames = {'roiList'};
                        req = {};
                        try
                            params = roiManual.setparam(struct());
                        catch
                            params = struct();
                        end
                    case 'roigrid'
                        typeName = 'roiGrid';
                        funcName = 'roiGrid.process';
                        inNames = {'images'};
                        outNames = {'roiList'};
                        req = {};
                        try
                            params = roiGrid.setparam(struct());
                        catch
                            params = struct();
                        end
                    case 'roiextract'
                        typeName = 'roiExtract';
                        funcName = 'roiExtract.process';
                        inNames = {'roiList'};
                        outNames = {'channels'};
                        req = {};
                        try
                            params = roiExtract.setparam(struct());
                        catch
                            params = struct();
                        end
                    case 'processor'
                        typeName = 'processor';
                        funcName = '';
                        inNames = {'inputChannels'};
                        outNames = {'dataSeries'};
                        req = {'pkg'};
                        params = struct('pkg','');
                    case 'classifier'
                        typeName = 'classifier';
                        funcName = '';
                        inNames = {'inputChannels'};
                        outNames = {'dataSeries'};
                        req = {'pkg'};
                        params = struct('pkg','');
                    otherwise
                        return;
                end

                nodeId = nextModuleId(typeName, pipeObj);
                if isempty(nodeName)
                    nodeName = nodeId;
                end

                node = struct();
                node.id = nodeId;
                node.name = nodeName;
                node.type = typeName;
                node.func = funcName;
                node.gui = '';
                node.guiMode = 'replace';
                node.paramRequired = req;
                node.inputs = inNames;
                node.outputs = outNames;
                node.params = params;
                node.enabled = true;
                node.status = '';
                node.pkg = pkgName;
                node.layout = [10 10 20 10];

                if strcmp(typeName,'processor') && ~isempty(pkgName)
                    node.func = [pkgName '.process'];
                    node.gui = 'processDataGUI';
                    node.params.pkg = pkgName;
                    try
                        p0 = feval([pkgName '.setparam'], struct());
                        if isstruct(p0)
                            fn = fieldnames(p0);
                            for fi=1:numel(fn)
                                node.params.(fn{fi}) = p0.(fn{fi});
                            end
                        end
                    catch
                    end
                end
                if strcmp(typeName,'classifier') && ~isempty(pkgName)
                    node.gui = 'classifierGUI';
                    node.params.pkg = pkgName;
                end
            end

            function out = nextModuleId(typeName, pipeObj)
                base = lower(regexprep(typeName,'[^a-zA-Z0-9]',''));
                if isempty(base)
                    base = 'node';
                end
                n = 1;
                out = [base '_' num2str(n)];
                if isempty(pipeObj.nodes)
                    return;
                end
                existing = cell(1,numel(pipeObj.nodes));
                for ii=1:numel(pipeObj.nodes)
                    existing{ii} = getStructFieldText(pipeObj.nodes(ii),'id','');
                end
                while any(strcmp(existing, out))
                    n = n + 1;
                    out = [base '_' num2str(n)];
                end
            end

            function v = getStructFieldText(S, name, defaultVal)
                v = defaultVal;
                if isstruct(S) && isfield(S,name)
                    val = S.(name);
                    if isnumeric(val)
                        v = num2str(val);
                    else
                        v = char(string(val));
                    end
                end
            end

            function iconFile = getPipelineModuleIcon(typeName)
                t = lower(strrep(char(string(typeName)), ' ', ''));
                switch t
                    case {'classifier'}
                        iconFile = 'brain.png';
                    case {'processor'}
                        iconFile = 'processor.png';
                    case {'dataloader','dataload'}
                        iconFile = 'data.png';
                    case {'roipattern','roiidentify','roiidentification','roimanual','roigrid','roiextract','roiextraction'}
                        iconFile = 'roi.png';
                    otherwise
                        iconFile = 'processor.png';
                end
            end
            function names = getProcessorPackageChoices()
                names = listPlusPackages('processor');
            end
            function names = getClassifierPackageChoices()
                names = listPlusPackages('classification');
            end

            function names = listPlusPackages(kind)
                names = {};
                try
                    here = fileparts(mfilename('fullpath'));
                    repoRoot = fileparts(fileparts(here));
                    root = fullfile(repoRoot, 'engine', kind);
                    if ~isfolder(root)
                        return;
                    end

                    d = dir(fullfile(root, '+*'));
                    d = d([d.isdir]);
                    names = cell(1, numel(d));
                    for ii = 1:numel(d)
                        names{ii} = erase(d(ii).name, '+');
                    end
                    names = unique(names, 'stable');
                catch
                    names = {};
                end
            end

            function [ok, typeName, pkgName, nodeName] = askPipelineModuleSpec()
                ok = false;
                typeName = '';
                pkgName = '';
                nodeName = '';

                typeChoices = {'dataLoader','roiPattern','roiManual','roiGrid','roiExtract','processor','classifier'};
                procPkgs = getProcessorPackageChoices();
                classPkgs = getClassifierPackageChoices();

                f = uifigure('Name','Add module','WindowStyle','modal','Position',[100 100 430 220]);
                if ~isempty(app.DetecDivUIFigure) && isvalid(app.DetecDivUIFigure)
                    p = app.DetecDivUIFigure.Position;
                    f.Position(1) = p(1) + max(20, floor((p(3)-f.Position(3))/2));
                    f.Position(2) = p(2) + max(20, floor((p(4)-f.Position(4))/2));
                end

                gl = uigridlayout(f,[4 2]);
                gl.RowHeight = {30,30,30,40};
                gl.ColumnWidth = {130,'1x'};
                gl.Padding = [10 10 10 10];

                uilabel(gl,'Text','Module type:');
                ddType = uidropdown(gl,'Items',typeChoices,'Value','dataLoader');

                uilabel(gl,'Text','Package:');
                ddPkg = uidropdown(gl,'Items',{'<none>'},'Value','<none>');
                ddPkg.Enable = 'off';

                uilabel(gl,'Text','Module name:');
                edName = uieditfield(gl,'text','Value','');

                pnlBtns = uipanel(gl);
                pnlBtns.Layout.Row = 4;
                pnlBtns.Layout.Column = [1 2];
                glb = uigridlayout(pnlBtns,[1 3]);
                glb.ColumnWidth = {'1x',90,90};

                btnOK = uibutton(glb,'Text','OK'); %#ok<NASGU>
                btnOK.Layout.Column = 2;
                btnCancel = uibutton(glb,'Text','Cancel'); %#ok<NASGU>
                btnCancel.Layout.Column = 3;

                function refreshPkg()
                    t = lower(char(ddType.Value));
                    switch t
                        case 'processor'
                            if isempty(procPkgs)
                                ddPkg.Items = {'<none>'};
                                ddPkg.Value = '<none>';
                            else
                                ddPkg.Items = procPkgs;
                                ddPkg.Value = procPkgs{1};
                            end
                            ddPkg.Enable = 'on';
                        case 'classifier'
                            if isempty(classPkgs)
                                ddPkg.Items = {'<none>'};
                                ddPkg.Value = '<none>';
                            else
                                ddPkg.Items = classPkgs;
                                ddPkg.Value = classPkgs{1};
                            end
                            ddPkg.Enable = 'on';
                        otherwise
                            ddPkg.Items = {'<none>'};
                            ddPkg.Value = '<none>';
                            ddPkg.Enable = 'off';
                    end
                end

                ddType.ValueChangedFcn = @(~,~) refreshPkg();
                refreshPkg();

                btnOK.ButtonPushedFcn = @onOK;
                btnCancel.ButtonPushedFcn = @onCancel;
                f.CloseRequestFcn = @onCancel;

                uiwait(f);

                if isvalid(f)
                    delete(f);
                end

                function onOK(~,~)
                    typeName = char(ddType.Value);
                    nodeName = strtrim(char(edName.Value));

                    if strcmpi(typeName,'processor') || strcmpi(typeName,'classifier')
                        if strcmp(ddPkg.Value,'<none>')
                            uialert(f,'Please select a package for this module type.','Missing package','Icon','warning');
                            return;
                        end
                        pkgName = char(ddPkg.Value);
                    else
                        pkgName = '';
                    end

                    ok = true;
                    uiresume(f);
                end

                function onCancel(~,~)
                    ok = false;
                    uiresume(f);
                end
            end

            function contextMenuPositionFcn(src,event,arg,str)

                cc=arg(1);
                pos=arg(2);

                proj=app.Data.Project{cc(1)};
                shallowObj=evalin('base',proj);

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Loading raw data from source files; Please wait...');
                d.Value=0.33;

                if numel(shallowObj.fov(pos).srcpath{1})>0
                    im=readImage(shallowObj.fov(pos),1,1);

                    if numel(im)==0 & numel(shallowObj.fov(pos).srcpath{1})>0
                        errordlg('The path to your raw data is incorrect; please update the raw data path !');
                    end
                end

                shallowObj.fov(pos).view(shallowObj.fov(pos).display.frame,[]);


                d.Value=0.9;
                d.Message='Data loaded...';
                pause(1);
                close(d);

            end

            function contextMenuRelinkRawDataFcn(src,event,arg,str) %#ok<INUSD>
                if strcmp(str, 'Project')
                    app.relinkTreeRawData(arg(1), []);
                else
                    app.relinkTreeRawData(arg(1), arg(2));
                end
            end

            function contextMenuDeleteROIsFcn(src,event,arg,str)


                cc=arg(1);

                if strcmp(str,'Classifier')
                    clas=app.Data.Classifier{cc};
                    clas=evalin('base',clas);
                end
                if strcmp(str,'Projectclassi')
                    proj=app.Data.Project{cc(1)};
                    pos=arg(2);
                    shallowObj=evalin('base',proj);
                    clas=shallowObj.processing.classification(pos);
                end
                if strcmp(str,'Projectpos')
                    proj=app.Data.Project{cc(1)};
                    pos=arg(2);
                    shallowObj=evalin('base',proj);
                    clas=shallowObj.fov(pos);
                end


                defroi=['1:'  num2str(numel(clas.roi)) ];
                prompt = {'ROIs that will be deleted:'};%,'Period between frames for each channel (in frames units):'};
                dlgtitle = 'Deleting ROIs';

                dims = [1 100];


                definput = {defroi};%, num2str(inte)};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                if numel(answer)==0
                    return;
                end

                selection=uiconfirm(app.DetecDivUIFigure,'This will erase selected ROIs; Proceed?','Warning');


                if strcmp(selection,'OK')

                    id=str2num(answer{1});
                    if numel(id)
                        pix=setxor(1:numel(clas.roi),id);

                        if numel(pix)==0
                            clas.roi=roi;
                            if ~strcmp(str,'Projectpos')
                                clas.channelName={};
                            end
                        else
                            clas.roi=clas.roi(pix);
                        end

                    end

                    RefreshtreewindowMenuSelected(app)
                end
            end

            function contextMenuDeletePositionFcn(src,event,arg,str)

                cc=arg(1);
                pos=arg(2);

                proj=app.Data.Project{cc(1)};
                shallowObj=evalin('base',proj);

                selection=uiconfirm(app.DetecDivUIFigure,'This will erase the selected position; Proceed?','Warning');

                if strcmp(selection,'OK')

                    pix=setxor(1:numel(shallowObj.fov),pos);
                    shallowObj.fov=shallowObj.fov(pix);
                    RefreshtreewindowMenuSelected(app)
                end

            end


            %             function contextMenuROIFcn(src,event,arg,str)
            %
            %                %  str,arg
            %
            %
            %                 if strcmp(str,'Projectclassirois')
            %                     cc=arg(1);
            %                 pos=arg(2);
            %                 ro=arg(3);
            %
            %
            %                 proj=app.Data.Project{cc};
            %                 shallowObj=evalin('base',proj);
            %                 roiObj=shallowObj.processing.classification(pos).roi(ro);
            %                 roiObj.view;
            %                 end
            %
            %                 if strcmp(str,'Projectposrois')
            %                  cc=arg(1);
            %                 pos=arg(2);
            %                 ro=arg(3);
            %
            %
            %                 proj=app.Data.Project{cc};
            %                 shallowObj=evalin('base',proj);
            %                 roiObj=shallowObj.fov(pos).roi(ro);
            %                 roiObj.view;
            %                 end
            %
            %                 if strcmp(str,'Classifierrois')
            %                  cc=arg(1);
            %                  ro=arg(2);
            %                  clas=app.Data.Classifier{cc};
            %                  clas=evalin('base',clas);
            %
            %                 roiObj=clas.roi(ro);
            %                 roiObj.view;
            %                   end
            %
            %
            %             end


        end

        function gatherVarsFromWorkspace(app)
            varlist=evalin('base','who');
            st=struct('Project',{{}},'Classifier',{{}},'Pipeline',{{}},'PipelineDisplay',{{}},'PipelineModules',{{}},'PipelineModuleIds',{{}},'PipelineModuleTypes',{{}},'Projectpos',{{}},'Projectclassi',{{}},'Projectprocess',{{}},'ProjectpipelineRun',{{}},'Projectposrois',{{}},'Projectclassirois',{{}},'Classifierrois',{{}},'ClassifierpipelineRun',{{}},'ClassifierpipelineRunPath',{{}});
            cc=0;
            cd=0;
            cp=0;
            app.Data={};
            pipelineSeen = containers.Map('KeyType','char','ValueType','logical');

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});


                if isa(tmp,'shallow')
                    %  disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};

                    for k=1:numel(tmp.processing.classification)
                        %  k
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];
                        st.Projectclassirois{cc}{k}={};

                    end

                    st.Projectclassi{cc}=tmpclassi;
                    tmpprocess={};

                    if isfield(tmp.processing,'processor')
                        for k=1:numel(tmp.processing.processor)
                            %  k
                            procList = tmp.processing.processor;
if ~isempty(procList)
    valid = arrayfun(@(p) isvalid(p), procList);
    if any(~valid)
        procList = procList(valid);
        tmp.processing.processor = procList;
    end
end

tmpprocess = {};
for k = 1:numel(procList)
    tmpprocess{end+1} = procList(k).strid; %#ok<AGROW>
end


                        end

                        st.Projectprocess{cc}=tmpprocess;
                    end

                    tmprun={};
                    [tmp, runsLoadedFromDisk] = app.ensureProjectPipelineRunsLoaded(tmp);
                    if runsLoadedFromDisk
                        try
                            assignin('base', varlist{i}, tmp);
                        catch
                        end
                    end
                    if isfield(tmp.processing,'pipelineRun') && ~isempty(tmp.processing.pipelineRun)
                        runs = tmp.processing.pipelineRun;
                        keep = true(1, numel(runs));
                        seenRuns = containers.Map('KeyType','char','ValueType','logical');
                        for k=1:numel(runs)
                            runObj = runs(k);
                            runKey = '';
                            try
                                if isprop(runObj,'path') && ~isempty(runObj.path)
                                    runKey = app.normalizeFsPath(runObj.path);
                                end
                            catch
                            end
                            if isempty(runKey)
                                parts = {};
                                try, parts{end+1} = char(string(runObj.runId)); catch, parts{end+1} = ['run_' num2str(k)]; end %#ok<AGROW>
                                try, parts{end+1} = app.normalizePipelineRootPath(runObj.templatePath); catch, parts{end+1} = ''; end %#ok<AGROW>
                                try
                                    if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef) && isfield(runObj.pipelineRef,'path')
                                        parts{end+1} = app.normalizePipelineRootPath(runObj.pipelineRef.path); %#ok<AGROW>
                                    end
                                catch
                                end
                                runKey = strjoin(parts, '|');
                            end
                            if isempty(runKey)
                                runKey = ['run_index_' num2str(k)];
                            end
                            if isKey(seenRuns, runKey)
                                keep(k) = false;
                            else
                                seenRuns(runKey) = true;
                            end
                        end
                        if any(~keep)
                            runs = runs(keep);
                            tmp.processing.pipelineRun = runs;
                        end
                        for k=1:numel(tmp.processing.pipelineRun)
                            runObj = tmp.processing.pipelineRun(k);
                            runName = runObj.runId;
                            if isempty(runName)
                                runName = ['run_' num2str(k)];
                            end
                            runStatus = '';
                            if isprop(runObj,'status') && ~isempty(runObj.status)
                                runStatus = char(string(runObj.status));
                            end
                            if isempty(runStatus)
                                tmprun{end+1} = runName; %#ok<AGROW>
                            else
                                tmprun{end+1} = [runName ' (' runStatus ')']; %#ok<AGROW>
                            end
                        end
                    end
                    st.ProjectpipelineRun{cc}=tmprun;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        if isprop(tmp.fov(k), 'srcpath') && iscell(tmp.fov(k).srcpath) && ...
                                numel(tmp.fov(k).srcpath) >= 1 && ~isempty(tmp.fov(k).srcpath{1})
                            tmpproj = [tmpproj [num2str(k) ' - ' tmp.fov(k).id]];
                            %  aa=tmp.fov(k).srcpath
                        end

                        st.Projectposrois{cc}{k}={};

                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'pipeline')
                    if app.isInternalPipelineGuiAlias(tmp, varlist{i})
                        continue;
                    end
                    pipelineKey = app.workspacePipelineDedupKey(tmp, varlist{i});
                    if ~isempty(pipelineKey) && isKey(pipelineSeen, pipelineKey)
                        continue;
                    end
                    if ~isempty(pipelineKey)
                        pipelineSeen(pipelineKey) = true;
                    end
                    if ~isfield(st,'Pipeline') || isempty(st.Pipeline)
                        st.Pipeline = {};
                        st.PipelineDisplay = {};
                        st.PipelineModules = {};
                        st.PipelineModuleIds = {};
                        st.PipelineModuleTypes = {};
                    end

                    cp = cp + 1;
                    st.Pipeline{cp} = varlist{i};
                    st.PipelineDisplay{cp} = app.pipelineTreeLabelFromObject(tmp, varlist{i});

                    modLabels = {};
                    modIds = {};
                    modTypes = {};
                    if isprop(tmp,'nodes') && ~isempty(tmp.nodes)
                        for kk = 1:numel(tmp.nodes)
                            node = tmp.nodes(kk);
                            nodeId = '';
                            if isfield(node,'id') && ~isempty(node.id)
                                nodeId = char(string(node.id));
                            else
                                nodeId = ['node_' num2str(kk)];
                            end

                            nodeName = nodeId;
                            if isfield(node,'name') && ~isempty(node.name)
                                nodeName = char(string(node.name));
                            end

                            nodeType = '';
                            if isfield(node,'type') && ~isempty(node.type)
                                nodeType = char(string(node.type));
                            end

                            nodePkg = '';
                            if isfield(node,'pkg') && ~isempty(node.pkg)
                                nodePkg = char(string(node.pkg));
                            end

                            label = nodeName;
                            if ~isempty(nodePkg)
                                label = [label ' {' nodePkg '}'];
                            end

                            modLabels{end+1} = label; %#ok<AGROW>
                            modIds{end+1} = nodeId; %#ok<AGROW>
                            modTypes{end+1} = nodeType; %#ok<AGROW>
                        end
                    end

                    st.PipelineModules{cp} = modLabels;
                    st.PipelineModuleIds{cp} = modIds;
                    st.PipelineModuleTypes{cp} = modTypes;
                end

                if isa(tmp,'classi')

                    %     disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};

                    st.Classifierrois{cd}={};
                    [runLabels, runPaths] = app.listClassifierPipelineRuns(tmp);
                    st.ClassifierpipelineRun{cd} = runLabels;
                    st.ClassifierpipelineRunPath{cd} = runPaths;

                end

            end

            %  st
            app.Data=st;

            %  st
        end

        function [shallowObj, loaded] = ensureProjectPipelineRunsLoaded(app, shallowObj)
            loaded = false;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            hasRuns = false;
            try
                hasRuns = isfield(shallowObj.processing, 'pipelineRun') && ~isempty(shallowObj.processing.pipelineRun);
            catch
                hasRuns = false;
            end
            if hasRuns
                return;
            end

            projectRoot = '';
            try
                projectRoot = fullfile(char(string(shallowObj.io.path)), char(string(shallowObj.io.file)));
            catch
                projectRoot = '';
            end
            if isempty(projectRoot)
                return;
            end

            pipelineRoot = fullfile(projectRoot, 'pipeline');
            if exist(pipelineRoot, 'dir') ~= 7
                return;
            end

            listpipe = dir(pipelineRoot);
            listpipe = listpipe(arrayfun(@(x)x.isdir, listpipe));
            listpipe = listpipe(~ismember({listpipe.name}, {'.','..'}));
            if isempty(listpipe)
                return;
            end

            order = zeros(1, numel(listpipe));
            for j = 1:numel(listpipe)
                suffix = regexp(listpipe(j).name, '\d+$', 'match');
                if ~isempty(suffix)
                    order(j) = str2double(suffix{1});
                else
                    order(j) = j;
                end
            end
            [~, ix] = sort(order);
            listpipe = listpipe(ix);

            pipeList = pipelineRun.empty;
            for j = 1:numel(listpipe)
                runPath = fullfile(pipelineRoot, listpipe(j).name);
                try
                    [runObj, msg] = pipelineRunLoad(runPath);
                    if isempty(runObj)
                        if ~isempty(msg)
                            runJson = fullfile(runPath, 'run.json');
                            isPendingRunWrite = contains(char(string(msg)), 'Pipeline run JSON not found') && exist(runJson, 'file') ~= 2;
                            if ~isPendingRunWrite
                                warning('detecdiv:PipelineRunLoadFailed', 'pipelineRunLoad failed for %s: %s', runPath, msg);
                            end
                        end
                        continue;
                    end
                    pipeList(end+1) = runObj; %#ok<AGROW>
                catch ME
                    warning('detecdiv:PipelineRunLoadError', 'pipelineRunLoad error for %s: %s', runPath, ME.message);
                end
            end

            if isempty(pipeList)
                return;
            end
            if ~isfield(shallowObj.processing, 'pipelineRun')
                shallowObj.processing.pipelineRun = pipelineRun.empty;
            end
            shallowObj.processing.pipelineRun = pipeList;
            loaded = true;
        end

        function tf = isInternalPipelineGuiAlias(app, pipeObj, varName) %#ok<INUSD>
            tf = strcmp(char(string(varName)), 'pipelineGUI2');
            if tf
                return;
            end
            try
                tf = strcmp(char(string(pipeObj.strid)), 'pipelineGUI2');
            catch
                tf = false;
            end
        end

        function key = workspacePipelineDedupKey(app, pipeObj, varName) %#ok<INUSD>
            key = '';
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end
            p = '';
            try
                p = char(string(pipeObj.path));
            catch
                p = '';
            end
            if ~isempty(strtrim(p))
                key = ['path:' lower(strrep(p, '/', filesep))];
                return;
            end
            name = '';
            try
                name = char(string(pipeObj.strid));
            catch
                name = '';
            end
            if ~isempty(strtrim(name))
                key = ['name:' lower(name)];
            else
                key = ['var:' lower(char(string(varName)))];
            end
        end

        function labels = buildRoiTreeLabels(app, roiList) %#ok<INUSD>
            labels = {};
            if isempty(roiList)
                return;
            end
            try
                if numel(roiList)==1 && isempty(roiList(1).id)
                    return;
                end
            catch
            end

            labels = cell(1, numel(roiList));
            for n = 1:numel(roiList)
                roiId = '';
                try
                    roiId = char(string(roiList(n).id));
                catch
                end
                labels{n} = [num2str(n) ' - ' roiId];
            end
        end

        function startRoiTreeBuild(app, parentNode, roiLabels, childTag, userDataPrefix)
            if isempty(roiLabels) || isempty(parentNode) || ~isvalid(parentNode)
                return;
            end
            app.stopRoiTreeBuild();

            [pth, ~, ~] = fileparts(which('detecdiv.mlapp'));
            app.RoiTreeBuildDialog = uiprogressdlg(app.DetecDivUIFigure, ...
                'Title', 'Please Wait...', ...
                'Message', sprintf('Generating classifier ROI list... 0/%d', numel(roiLabels)), ...
                'Value', 0, ...
                'Cancelable', 'on');
            app.RoiTreeBuildState = struct( ...
                'parentNode', parentNode, ...
                'roiLabels', {roiLabels}, ...
                'childTag', char(string(childTag)), ...
                'userDataPrefix', userDataPrefix, ...
                'roiIcon', fullfile(pth, 'roi.png'), ...
                'nextIndex', 1, ...
                'chunkSize', 10);
            app.RoiTreeBuildTimer = timer( ...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 0.05, ...
                'StartDelay', 0.01, ...
                'BusyMode', 'drop', ...
                'TimerFcn', @(~,~) app.buildNextRoiTreeChunk());
            start(app.RoiTreeBuildTimer);
            drawnow limitrate nocallbacks;
        end

        function buildNextRoiTreeChunk(app)
            try
                state = app.RoiTreeBuildState;
                if isempty(app) || ~isvalid(app) || ~isstruct(state) || ...
                        ~isfield(state, 'parentNode') || isempty(state.parentNode) || ...
                        ~isvalid(state.parentNode)
                    app.stopRoiTreeBuild();
                    return;
                end

                nRoi = numel(state.roiLabels);
                d = app.RoiTreeBuildDialog;
                if ~isempty(d) && isvalid(d) && d.CancelRequested
                    app.stopRoiTreeBuild();
                    return;
                end
                firstIndex = state.nextIndex;
                lastIndex = min(nRoi, firstIndex + state.chunkSize - 1);
                for n = firstIndex:lastIndex
                    uitreenode(state.parentNode, ...
                        'Text', state.roiLabels{n}, ...
                        'Tag', state.childTag, ...
                        'UserData', [state.userDataPrefix, n], ...
                        'Icon', state.roiIcon);
                end

                state.nextIndex = lastIndex + 1;
                app.RoiTreeBuildState = state;
                d = app.RoiTreeBuildDialog;
                if ~isempty(d) && isvalid(d)
                    d.Value = lastIndex ./ nRoi;
                    d.Message = sprintf('Generating classifier ROI list... %d/%d', lastIndex, nRoi);
                end
                drawnow limitrate;

                if lastIndex >= nRoi
                    app.stopRoiTreeBuild();
                end
            catch ME
                warning('DetecDiv:Tree:RoiBuildFailed', ...
                    'Could not finish the classifier ROI tree: %s', ME.message);
                app.stopRoiTreeBuild();
            end
        end

        function stopRoiTreeBuild(app)
            t = app.RoiTreeBuildTimer;
            app.RoiTreeBuildTimer = [];
            if ~isempty(t)
                try, stop(t); catch, end
                try, delete(t); catch, end
            end
            d = app.RoiTreeBuildDialog;
            app.RoiTreeBuildDialog = [];
            if ~isempty(d)
                app.safeCloseProgressDialog(d);
            end
            app.RoiTreeBuildState = struct();
        end

        function tf = treeNodeHasChildTag(app, node, tagName) %#ok<INUSD>
            tf = false;
            try
                children = node.Children;
                for ii = 1:numel(children)
                    if strcmp(children(ii).Tag, tagName)
                        tf = true;
                        return;
                    end
                end
            catch
                tf = false;
            end
        end

        function ensureProjectPositionRoiTreeExpanded(app, positionNode, shallowObj, projIdx, posIdx)
            if app.treeNodeHasChildTag(positionNode, 'Projectposrois')
                try, expand(positionNode); catch, end
                return;
            end

            roiLabels = buildRoiTreeLabels(app, shallowObj.fov(posIdx).roi);
            if isempty(roiLabels)
                return;
            end

            d = uiprogressdlg(app.DetecDivUIFigure, ...
                'Title', 'Please Wait...', ...
                'Message', 'Generating ROI list...', ...
                'Value', 0);
            cleanupObj = onCleanup(@() app.safeCloseProgressDialog(d)); %#ok<NASGU>
            drawnow limitrate;

            [pth, ~, ~] = fileparts(which('detecdiv.mlapp'));
            nRoi = numel(roiLabels);
            for n = 1:nRoi
                if n == 1 || n == nRoi || mod(n, 10) == 0
                    d.Value = n ./ nRoi;
                    d.Message = ['Generating ROI list... ' num2str(n) '/' num2str(nRoi)];
                    drawnow limitrate;
                end
                uitreenode(positionNode, ...
                    'Text', roiLabels{n}, ...
                    'Tag', 'Projectposrois', ...
                    'UserData', [projIdx, posIdx, n], ...
                    'Icon', fullfile(pth, 'roi.png'));
            end

            try, expand(positionNode); catch, end
            drawnow limitrate;
        end


        function check = checkImagePath(app,proj)

            check=ones(1,numel(proj.fov));

            d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                'Message','Checking raw data path in the project...');
            d.Value=0.0;
            for i=1:numel(proj.fov)
                d.Value=i./numel(proj.fov);
                if numel(proj.fov(i).srcpath{1})>0
                    im=readImage(proj.fov(i),1,1);

                    if numel(im)==0 & numel(proj.fov(1).srcpath{1})>0
                        check(i)=0;
                    end
                end
            end


            if numel(find(check==0))
                d.Message='Some raw data paths are invalid. It will be impossible to display the raw images!';
                pause(5);
            else
                d.Message='The paths are OK!';
                pause(1);
            end

            close(d);
        end
    end

    methods (Access = private)

        function relinkTreeRawData(app, projectIdx, fovIdx)
            if projectIdx < 1 || projectIdx > numel(app.Data.Project)
                uialert(app.DetecDivUIFigure, 'The selected project is no longer available.', ...
                    'Relink raw data', 'Icon', 'warning');
                return;
            end

            projectVar = app.Data.Project{projectIdx};
            try
                shallowObj = evalin('base', projectVar);
            catch
                shallowObj = [];
            end
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                uialert(app.DetecDivUIFigure, 'The selected shallow project is not available in the workspace.', ...
                    'Relink raw data', 'Icon', 'warning');
                return;
            end

            try
                detecdiv_hub_assert_project_writable(shallowObj);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Relink raw data', 'Icon', 'error');
                return;
            end

            if ~isempty(fovIdx) && (fovIdx < 1 || fovIdx > numel(shallowObj.fov))
                uialert(app.DetecDivUIFigure, 'The selected position is no longer available.', ...
                    'Relink raw data', 'Icon', 'warning');
                return;
            end

            startDir = pwd;
            try
                sourceFov = 1;
                if ~isempty(fovIdx), sourceFov = fovIdx; end
                candidates = shallowObj.fov(sourceFov).srcpath;
                for ii = 1:numel(candidates)
                    if ~isempty(candidates{ii}) && isfolder(candidates{ii})
                        startDir = candidates{ii};
                        break;
                    end
                end
            catch
                try
                    if isfolder(shallowObj.io.path), startDir = shallowObj.io.path; end
                catch
                end
            end

            root = uigetdir(startDir, 'Select raw data root or dataset folder for relink');
            if isequal(root, 0)
                return;
            end

            d = [];
            try
                d = uiprogressdlg(app.DetecDivUIFigure, ...
                    'Title', 'Relink raw data', ...
                    'Message', 'Rebasing FOV source paths...', ...
                    'Indeterminate', 'on');
                drawnow;
            catch
            end
            cleanupDlg = onCleanup(@() app.safeCloseProgressDialog(d)); %#ok<NASGU>

            try
                if isempty(fovIdx)
                    [shallowObj, report] = detecdiv_paths_relink_project(shallowObj, root, ...
                        'Force', true, 'Debug', false);
                    scopeText = 'project';
                else
                    [shallowObj, report] = detecdiv_paths_relink_project(shallowObj, root, ...
                        'Fovs', fovIdx, 'Force', true, 'Debug', false);
                    scopeText = sprintf('position %d', fovIdx);
                end

                if isempty(report)
                    uialert(app.DetecDivUIFigure, 'No FOV/channel entry could be relinked.', ...
                        'Relink raw data', 'Icon', 'warning');
                    return;
                end

                okCount = sum([report.ok]);
                changedCount = sum(~strcmp({report.before}, {report.after}));
                failedCount = numel(report) - okCount;
                if okCount == 0
                    errors = string({report.error});
                    errors = errors(strlength(errors) > 0);
                    detail = '';
                    if ~isempty(errors)
                        detail = sprintf('\n\nCause: %s', char(errors(1)));
                    end
                    app.safeCloseProgressDialog(d);
                    d = [];
                    uialert(app.DetecDivUIFigure, sprintf( ...
                        ['No raw-data path could be resolved for %s (0/%d).' ...
                         '\nSelect the dataset folder containing Pos* folders, then retry.%s'], ...
                        scopeText, numel(report), detail), ...
                        'Relink raw data', 'Icon', 'warning');
                    return;
                end
                assignin('base', projectVar, shallowObj);

                app.safeCloseProgressDialog(d);
                d = [];
                app.saveShallowProjectWithProgress(shallowObj, 'Saving relinked project...');

                gatherVarsFromWorkspace(app);
                displayNodes(app);
                if failedCount > 0
                    uialert(app.DetecDivUIFigure, sprintf( ...
                        'Raw data partially relinked for %s.\n\nReady entries: %d/%d\nChanged entries: %d', ...
                        scopeText, okCount, numel(report), changedCount), ...
                        'Relink raw data', 'Icon', 'warning');
                else
                    uialert(app.DetecDivUIFigure, sprintf( ...
                        'Raw data relinked for %s.\n\nReady entries: %d/%d\nChanged entries: %d', ...
                        scopeText, okCount, numel(report), changedCount), ...
                        'Relink raw data', 'Icon', 'success');
                end
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Relink raw data failed', 'Icon', 'error');
            end
        end

        function  displayClassiImage(app,clas)

            selectedNode=app.Tree.SelectedNodes;


            if numel(selectedNode.NodeData)==0
                loade=1;

                pth=clas.path;
                sam=fullfile(pth,'sampleImage.png');

                if exist(sam)
                    im=imread(sam);

                else

                    [ k, himg]=clas.displayFormattedTrainingSet('Display','Nimages',9);
                    %    himg
                    im=himg;
                    close

                end
                selectedNode.NodeData=im;
            else
                im=selectedNode.NodeData;
            end

            imshow(im,'parent',app.UIAxes);
        end


        function [ok,node,pipeObj] = getPipelineNodeByIndex(app, pipeIdx, modIdx)
            ok = false;
            node = struct();
            pipeObj = [];

            if pipeIdx > numel(app.Data.Pipeline)
                return;
            end

            pipeVar = app.Data.Pipeline{pipeIdx};
            try
                pipeObj = evalin('base', pipeVar);
            catch
                return;
            end

            if ~isa(pipeObj,'pipeline') || ~isprop(pipeObj,'nodes') || modIdx > numel(pipeObj.nodes)
                pipeObj = [];
                return;
            end

            node = pipeObj.nodes(modIdx);
            ok = true;
        end

        function [nType, modObj] = buildPipelineModuleObject(app, node) %#ok<INUSD>
            nType = '';
            modObj = [];

            if ~(isstruct(node) && isfield(node,'type') && ~isempty(node.type))
                return;
            end

            nType = lower(char(string(node.type)));

            if strcmp(nType,'processor')
                tmpProc = process(tempdir, 'pipeline_module', randi(1e9));

                pkgName = '';
                if isfield(node,'pkg') && ~isempty(node.pkg)
                    pkgName = char(string(node.pkg));
                end

                if ~isempty(pkgName)
                    tmpProc.processFun = [pkgName '.process'];
                    try
                        p0 = feval([pkgName '.setparam'], struct());
                    catch
                        p0 = struct();
                    end
                    if isstruct(p0)
                        tmpProc.processArg = p0;
                    end
                elseif isfield(node,'func') && ~isempty(node.func)
                    tmpProc.processFun = char(string(node.func));
                end

                if isfield(node,'params') && isstruct(node.params)
                    if isempty(tmpProc.processArg) || ~isstruct(tmpProc.processArg)
                        tmpProc.processArg = node.params;
                    else
                        fn = fieldnames(node.params);
                        for fi = 1:numel(fn)
                            tmpProc.processArg.(fn{fi}) = node.params.(fn{fi});
                        end
                    end
                end

                if isfield(node,'id') && ~isempty(node.id)
                    tmpProc.strid = char(string(node.id));
                end

                modObj = tmpProc;
                return;
            end

            if strcmp(nType,'classifier')
                tmpClassi = classi(tempdir, 'pipeline_module', randi(1e9));

                if isfield(node,'id') && ~isempty(node.id)
                    tmpClassi.strid = char(string(node.id));
                end

                pkgName = '';
                if isfield(node,'pkg') && ~isempty(node.pkg)
                    pkgName = char(string(node.pkg));
                end

                if ~isempty(pkgName)
                    tmpClassi.classifierPkg = pkgName;
                    if isempty(tmpClassi.classifyFun)
                        tmpClassi.classifyFun = [pkgName '.classify'];
                    end
                    if isempty(tmpClassi.trainingFun)
                        tmpClassi.trainingFun = [pkgName '.train'];
                    end

                    tmpClassi.category = {'Image'};
                    try
                        spec = feval([pkgName '.executionSpec'], tmpClassi);
                        if isstruct(spec) && isfield(spec,'category') && ~isempty(spec.category)
                            tmpClassi.category = classiNormalizeCategory(spec.category);
                        elseif strcmpi(pkgName,'cnn_lstm')
                            tmpClassi.category = {'LSTM'};
                        end
                    catch
                        if strcmpi(pkgName,'cnn_lstm')
                            tmpClassi.category = {'LSTM'};
                        end
                    end
                else
                    tmpClassi.category = {'Image'};
                end

                if isfield(node,'func') && ~isempty(node.func)
                    tmpClassi.classifyFun = char(string(node.func));
                end

                if isfield(node,'params') && isstruct(node.params)
                    if isfield(node.params,'classes') && ~isempty(node.params.classes)
                        cls = node.params.classes;
                        if isstring(cls), cls = cellstr(cls); end
                        if ischar(cls), cls = {cls}; end
                        tmpClassi.classes = cls;
                    end
                end

                tmpClassi.category = classiNormalizeCategory(tmpClassi.category);
                modObj = tmpClassi;
                return;
            end
        end

        function openPipelineModuleByIndex(app, pipeIdx, modIdx)
            [ok,node,pipeObj] = app.getPipelineNodeByIndex(pipeIdx, modIdx);
            if ~ok
                return;
            end

            [nType, modObj] = app.buildPipelineModuleObject(node);
            switch nType
                case 'processor'
                    processDataGUI([], modObj);
                case 'classifier'
                    classifierGUI(modObj);
                otherwise
                    app.openPipelineWithContext(pipeObj);
            end
        end

        function openPipelineWithContext(app, pipeObj, progressDlg)
            if nargin < 3
                progressDlg = [];
            end
            if isempty(progressDlg) || ~isvalid(progressDlg)
                progressDlg = uiprogressdlg(app.DetecDivUIFigure, ...
                    'Title', 'Please wait', ...
                    'Message', 'Opening pipeline editor...', ...
                    'Indeterminate', 'on');
            end
            cleanupObj = onCleanup(@() app.safeCloseProgressDialog(progressDlg)); %#ok<NASGU>
            app.updatePipelineOpenProgress(progressDlg, 'Reloading pipeline template...');
            pipeObj = app.reloadPipelineTemplateFromDiskIfAvailable(pipeObj);
            projectObj = [];
            app.updatePipelineOpenProgress(progressDlg, 'Looking for linked project...');
            [found, projectObj] = app.findLinkedProjectForPipeline(pipeObj);
            app.updatePipelineOpenProgress(progressDlg, 'Launching pipeline editor...');
            if found
                pipeline2(projectObj, pipeObj);
            else
                pipeline2([], pipeObj);
            end
        end

        function openPipelineEditorWithProgress(app, projectObj, targetObj, message, varargin)
            if nargin < 4 || isempty(message)
                message = 'Opening pipeline editor...';
            end
            d = uiprogressdlg(app.DetecDivUIFigure, ...
                'Title', 'Please wait', ...
                'Message', message, ...
                'Indeterminate', 'on');
            cleanupObj = onCleanup(@() app.safeCloseProgressDialog(d)); %#ok<NASGU>
            drawnow;
            pipeline2(projectObj, targetObj, varargin{:});
        end

        function updatePipelineOpenProgress(app, dlg, message) %#ok<INUSD>
            try
                if ~isempty(dlg) && isvalid(dlg)
                    dlg.Message = message;
                    drawnow limitrate nocallbacks;
                end
            catch
            end
        end

        function safeCloseProgressDialog(app, dlg) %#ok<INUSD>
            try
                if ~isempty(dlg) && isvalid(dlg)
                    close(dlg);
                end
            catch
            end
        end

        function text = describePipelineTemplate(app, pipeObj) %#ok<INUSD>
            text = '';
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end
            nodes = pipeObj.nodes;
            if isempty(nodes)
                text = 'Modules: none';
                return;
            end
            text = ['Modules:' newline];
            for iNode = 1:numel(nodes)
                node = nodes(iNode);
                nodeId = '';
                nodeType = '';
                nodePkg = '';
                try
                    if isfield(node,'id') && ~isempty(node.id), nodeId = char(string(node.id)); end
                    if isfield(node,'type') && ~isempty(node.type), nodeType = char(string(node.type)); end
                    if isfield(node,'pkg') && ~isempty(node.pkg), nodePkg = char(string(node.pkg)); end
                catch
                end
                if isempty(nodeId)
                    nodeId = ['node_' num2str(iNode)];
                end
                parts = {nodeId};
                if ~isempty(nodeType), parts{end+1} = ['[' nodeType ']']; end %#ok<AGROW>
                if ~isempty(nodePkg), parts{end+1} = ['package=' nodePkg]; end %#ok<AGROW>
                text = [text '- ' strjoin(parts, ' ') newline]; %#ok<AGROW>
            end
        end

        function pipeObj = reloadPipelineTemplateFromDiskIfAvailable(app, pipeObj) %#ok<INUSD>
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end
            pipePath = '';
            try
                pipePath = char(string(pipeObj.path));
            catch
                pipePath = '';
            end
            if isempty(strtrim(pipePath))
                return;
            end
            jsonPath = pipePath;
            if exist(jsonPath, 'dir') == 7
                jsonPath = fullfile(jsonPath, 'pipeline.json');
            end
            if exist(jsonPath, 'file') ~= 2
                return;
            end
            try
                [freshPipe, msg] = pipelineLoad(jsonPath);
                if ~isempty(freshPipe)
                    pipeObj = freshPipe;
                elseif ~isempty(msg)
                    warning('detecdiv:PipelineReloadFailed', '%s', msg);
                end
            catch ME
                warning('detecdiv:PipelineReloadFailed', '%s', ME.message);
            end
        end

        function runObj = getProjectRunByIndex(app, projIdx, runIdx)
            runObj = [];
            if projIdx > numel(app.Data.Project)
                return;
            end
            projVar = app.Data.Project{projIdx};
            shallowObj = evalin('base', projVar);
            if ~isfield(shallowObj.processing,'pipelineRun') || runIdx > numel(shallowObj.processing.pipelineRun)
                return;
            end
            runObj = shallowObj.processing.pipelineRun(runIdx);
        end

        function label = formatPipelineRunLabel(app, runObj, fallbackIdx) %#ok<INUSD>
            label = '';
            try
                if isprop(runObj,'runId') && ~isempty(runObj.runId)
                    label = char(string(runObj.runId));
                end
            catch
            end
            if isempty(label)
                label = ['run_' num2str(fallbackIdx)];
            end
        end

        function [runMode, runStatus] = summarizePipelineRun(app, runObj) %#ok<INUSD>
            runMode = 'local';
            runStatus = 'unknown';

            try
                if isprop(runObj,'status') && ~isempty(runObj.status)
                    runStatus = char(string(runObj.status));
                end
            catch
            end

            try
                if isstruct(runObj.ctx) && isfield(runObj.ctx,'hub') && isstruct(runObj.ctx.hub)
                    runMode = 'hub';
                    if isfield(runObj.ctx.hub,'status') && ~isempty(runObj.ctx.hub.status)
                        runStatus = char(string(runObj.ctx.hub.status));
                    end
                end
            catch
            end

            if startsWith(runStatus,'hub_')
                runMode = 'hub';
                runStatus = extractAfter(runStatus, 4);
                if isempty(runStatus)
                    runStatus = 'unknown';
                end
            elseif isempty(runStatus)
                runStatus = 'unknown';
            end
        end

        function [found, shallowObj] = findLinkedProjectForPipeline(app, pipeObj)
            found = false;
            shallowObj = [];

            [found, projectIdx] = app.findLinkedProjectIndicesForPipeline(pipeObj);
            if ~found || isempty(projectIdx)
                return;
            end

            if projectIdx(1) <= numel(app.Data.Project)
                projVar = app.Data.Project{projectIdx(1)};
                try
                    shallowObj = evalin('base', projVar);
                catch
                    shallowObj = [];
                end
            end
        end

        function [found, projectIdx] = findLinkedProjectIndicesForPipeline(app, pipeObj)
            found = false;
            projectIdx = [];
            if isempty(pipeObj) || ~isa(pipeObj,'pipeline')
                return;
            end

            targetKey = app.normalizePipelineRootPath(pipeObj.path);
            if isempty(targetKey)
                return;
            end

            for iProj = 1:numel(app.Data.Project)
                projVar = app.Data.Project{iProj};
                try
                    obj = evalin('base', projVar);
                catch
                    continue;
                end
                if ~isa(obj,'shallow')
                    continue;
                end

                if app.isPipelineInsideProjectFolder(obj, pipeObj)
                    projectIdx(end+1) = iProj; %#ok<AGROW>
                    found = true;
                    continue;
                end

                projectKey = app.normalizePipelineRootPath(app.getProjectDefaultPipelinePath(obj));
                if ~isempty(projectKey) && strcmp(projectKey, targetKey)
                    projectIdx(end+1) = iProj; %#ok<AGROW>
                    found = true;
                    continue;
                end

                try
                    runs = obj.processing.pipelineRun;
                catch
                    runs = [];
                end
                for iRun = 1:numel(runs)
                    runPath = '';
                    try
                        if isprop(runs(iRun),'pipelineRef') && isstruct(runs(iRun).pipelineRef) && isfield(runs(iRun).pipelineRef,'path')
                            runPath = char(string(runs(iRun).pipelineRef.path));
                        elseif isprop(runs(iRun),'templatePath')
                            runPath = char(string(runs(iRun).templatePath));
                        end
                    catch
                    end
                    if ~isempty(runPath) && strcmp(app.normalizePipelineRootPath(runPath), targetKey)
                        projectIdx(end+1) = iProj; %#ok<AGROW>
                        found = true;
                        break;
                    end
                end
            end

            if ~isempty(projectIdx)
                projectIdx = unique(projectIdx, 'stable');
                found = true;
            end
        end

        function tf = isPipelineInsideProjectFolder(app, shallowObj, pipeObj) %#ok<INUSD>
            tf = false;
            if isempty(shallowObj) || isempty(pipeObj) || ~isa(shallowObj,'shallow') || ~isa(pipeObj,'pipeline')
                return;
            end

            projectRoot = '';
            try
                if isprop(shallowObj,'io') && isstruct(shallowObj.io) && isfield(shallowObj.io,'path') && isfield(shallowObj.io,'file')
                    projectRoot = fullfile(char(string(shallowObj.io.path)), char(string(shallowObj.io.file)));
                end
            catch
            end
            if isempty(projectRoot) || isempty(pipeObj.path)
                return;
            end

            rootKey = app.normalizeFsPath(projectRoot);
            pipeKey = app.normalizeFsPath(pipeObj.path);
            if isempty(rootKey) || isempty(pipeKey)
                return;
            end

            tf = strcmp(pipeKey, rootKey) || startsWith(pipeKey, [rootKey '/']);
        end


        function autoLoadPipelinesForProjectRuns(app, shallowObj)
            if isempty(shallowObj) || ~isa(shallowObj,'shallow')
                return;
            end

            loaded = containers.Map('KeyType','char','ValueType','logical');
            existingPaths = app.listLoadedPipelinePaths();
            runsChanged = false;

            % 1) explicit project -> default pipeline link
            defaultPath = app.getProjectDefaultPipelinePath(shallowObj);
            if ~isempty(defaultPath)
                [~, existingPaths, loaded] = app.loadPipelineTemplateIfNeeded(defaultPath, existingPaths, loaded, 'project default');
            end

            % 2) pipelines referenced by existing runs
            if isfield(shallowObj.processing,'pipelineRun') && ~isempty(shallowObj.processing.pipelineRun)
                runs = shallowObj.processing.pipelineRun;
                for iRun = 1:numel(runs)
                    runObj = runs(iRun);
                    prevPath = '';

                    if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef)
                        if isfield(runObj.pipelineRef,'path') && ~isempty(runObj.pipelineRef.path)
                            prevPath = char(string(runObj.pipelineRef.path));
                        end
                    end
                    if isempty(prevPath) && isprop(runObj,'templatePath') && ~isempty(runObj.templatePath)
                        prevPath = char(string(runObj.templatePath));
                    end
                    [pipeObj, ~] = app.resolvePipelineFromRun(runObj, shallowObj);
                    if isempty(pipeObj)
                        continue;
                    end

                    runObj = app.backfillRunPipelineRef(runObj, pipeObj, shallowObj);
                    shallowObj.processing.pipelineRun(iRun) = runObj;
                    if ~strcmp(app.normalizePipelineRootPath(prevPath), app.normalizePipelineRootPath(runObj.pipelineRef.path))
                        runsChanged = true;
                    end

                    label = 'pipeline run';
                    try
                        if isprop(runObj,'runId') && ~isempty(runObj.runId)
                            label = ['run ' char(string(runObj.runId))];
                        end
                    catch
                    end

                    loadablePipe = pipeObj;
                    if ~(isa(loadablePipe,'pipeline') && isprop(loadablePipe,'path') && ~isempty(loadablePipe.path))
                        try
                            if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef) ...
                                    && isfield(runObj.pipelineRef,'path') && ~isempty(runObj.pipelineRef.path)
                                [tmpPipe, ~] = pipelineLoad(runObj.pipelineRef.path);
                                if ~isempty(tmpPipe)
                                    loadablePipe = tmpPipe;
                                end
                            end
                        catch
                        end
                    end
                    if ~(isa(loadablePipe,'pipeline') && isprop(loadablePipe,'path') && ~isempty(loadablePipe.path))
                        continue;
                    end

                    key = app.normalizePipelineRootPath(loadablePipe.path);
                    if isempty(key)
                        continue;
                    end
                    if isKey(existingPaths, key) || isKey(loaded, key)
                        continue;
                    end

                    varName = app.nextPipelineVarName(loadablePipe);
                    assignin('base', varName, loadablePipe);
                    loaded(key) = true;
                    existingPaths(key) = true;

                    try
                        app.registerRecentPipeline(string(fullfile(loadablePipe.path, 'pipeline.json')));
                    catch
                    end
                end
            end

            % 3) fallback: scan project folder for pipeline.json if no explicit link exists
            if isempty(defaultPath)
                candidates = app.resolveProjectPipelineJsonCandidates(shallowObj);
                if ~isempty(candidates)
                    for iPath = 1:numel(candidates)
                        [pipeObj, existingPaths, loaded] = app.loadPipelineTemplateIfNeeded(candidates{iPath}, existingPaths, loaded, 'project scan');
                        if iPath == 1 && ~isempty(pipeObj)
                            if app.setProjectDefaultPipelineRef(shallowObj, pipeObj)
                                try
                                    app.saveShallowProjectWithProgress(shallowObj, 'Saving project pipeline link...', 'shallowObj');
                                catch
                                end
                            end
                        end
                    end
                end
            end

            if runsChanged
                try
                    app.saveShallowProjectWithProgress(shallowObj, 'Saving project pipeline links...', 'shallowObj');
                catch
                end
            end

        end

        function [pipeObj, existingPaths, loaded] = loadPipelineTemplateIfNeeded(app, pipePath, existingPaths, loaded, sourceLabel)
            pipeObj = [];
            if nargin < 5 || isempty(sourceLabel)
                sourceLabel = 'pipeline';
            end
            if isempty(pipePath)
                return;
            end

            key = app.normalizePipelineRootPath(pipePath);
            if isempty(key)
                return;
            end
            if isKey(existingPaths, key) || isKey(loaded, key)
                return;
            end

            [pipeObj, msg] = pipelineLoad(pipePath);
            if isempty(pipeObj)
                if ~isempty(msg)
                    warning('detecdiv:autoLoadPipeline', ...
                        '%s: cannot load pipeline at %s (%s)', ...
                        sourceLabel, pipePath, msg);
                else
                    warning('detecdiv:autoLoadPipeline', ...
                        '%s: cannot load pipeline at %s', ...
                        sourceLabel, pipePath);
                end
                return;
            end

            varName = app.nextPipelineVarName(pipeObj);
            assignin('base', varName, pipeObj);

            loaded(key) = true;
            existingPaths(key) = true;

            try
                app.registerRecentPipeline(string(fullfile(pipeObj.path, 'pipeline.json')));
            catch
            end
        end


        function paths = listLoadedPipelinePaths(app) %#ok<INUSD>
            paths = containers.Map('KeyType','char','ValueType','logical');
            try
                vars = evalin('base','who');
            catch
                return;
            end

            for iVar = 1:numel(vars)
                vname = vars{iVar};
                try
                    obj = evalin('base', vname);
                catch
                    continue;
                end
                if ~isa(obj,'pipeline')
                    continue;
                end
                if ~isprop(obj,'path') || isempty(obj.path)
                    continue;
                end
                key = app.normalizePipelineRootPath(obj.path);
                if ~isempty(key)
                    paths(key) = true;
                end
            end
        end

        function varName = nextPipelineVarName(app, pipeObj) %#ok<INUSD>
            baseName = char(string(pipeObj.strid));
            if isempty(baseName)
                baseName = 'pipeline';
            end
            baseName = matlab.lang.makeValidName(baseName);

            varName = baseName;
            idx = 1;
            while evalin('base', sprintf('exist(''%s'',''var'')', varName))
                try
                    obj = evalin('base', varName);
                    if isa(obj,'pipeline') && isprop(obj,'path') && isprop(pipeObj,'path')
                        if strcmp(app.normalizePipelineRootPath(obj.path), app.normalizePipelineRootPath(pipeObj.path))
                            return;
                        end
                    end
                catch
                end
                idx = idx + 1;
                varName = sprintf('%s_%d', baseName, idx);
            end
        end

        function label = pipelineTreeLabelFromObject(app, pipeObj, fallbackVarName) %#ok<INUSD>
            label = '';
            if nargin < 3
                fallbackVarName = '';
            end
            try
                if ~isempty(pipeObj) && isa(pipeObj,'pipeline') && isprop(pipeObj,'strid') && ~isempty(pipeObj.strid)
                    label = strtrim(char(string(pipeObj.strid)));
                end
            catch
            end
            if isempty(label)
                try
                    if ~isempty(pipeObj) && isa(pipeObj,'pipeline') && isprop(pipeObj,'path') && ~isempty(pipeObj.path)
                        label = app.pipelineTreeLabelFromJson(fullfile(char(string(pipeObj.path)), 'pipeline.json'));
                    end
                catch
                end
            end
            if isempty(label)
                label = char(string(fallbackVarName));
            end
        end

        function label = pipelineTreeLabelFromJson(app, pipelineJson)
            label = '';
            if isempty(pipelineJson)
                return;
            end
            % Recent pipelines are references, not loaded pipeline objects.
            % Derive their tree label from the path so startup never reads a
            % pipeline.json (which may live on a slow/offline network share).
            [folder, ~, ~] = fileparts(char(string(pipelineJson)));
            [parentFolder, folderName] = fileparts(folder);
            label = folderName;
            if strcmpi(folderName, 'pipeline')
                [~, parentName] = fileparts(parentFolder);
                if ~isempty(parentName)
                    label = parentName;
                end
            end
            if isempty(label)
                [~, label] = fileparts(char(string(pipelineJson)));
            end
        end

        function key = normalizeFsPath(app, inPath) %#ok<INUSD>
            key = '';
            if isempty(inPath)
                return;
            end
            try
                p = char(string(inPath));
            catch
                return;
            end
            p = strrep(p,'\','/');
            p = lower(p);
            p = regexprep(p,'/+$','');
            key = p;
        end

        function key = normalizePipelineRootPath(app, inPath)
            key = '';
            if isempty(inPath)
                return;
            end
            try
                p = char(string(inPath));
            catch
                return;
            end
            [~, name, ext] = fileparts(p);
            if strcmpi([name ext], 'pipeline.json')
                p = fileparts(p);
            end
            key = app.normalizeFsPath(p);
        end

        function [pipeObj, msg] = resolvePipelineFromRun(app, runObj, shallowObj)
            pipeObj = [];
            msg = 'Could not resolve pipeline for this run.';

            try
                spec = runObj.ctx.pipelineSpec;
                if isstruct(spec) && isfield(spec,'nodes') && ~isempty(spec.nodes)
                    pipeObj = spec;
                    if ~isfield(pipeObj,'edges') || isempty(pipeObj.edges)
                        pipeObj.edges = struct([]);
                    end
                    msg = '';
                    return;
                end
            catch
            end

            if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef)
                if isfield(runObj.pipelineRef,'path') && ~isempty(runObj.pipelineRef.path)
                    [pipeObj, m] = pipelineLoad(runObj.pipelineRef.path);
                    if ~isempty(pipeObj)
                        msg = '';
                        return;
                    end
                    if ~isempty(m)
                        msg = m;
                    end
                end
            end

            if isprop(runObj,'templatePath') && ~isempty(runObj.templatePath)
                [pipeObj, m] = pipelineLoad(runObj.templatePath);
                if ~isempty(pipeObj)
                    msg = '';
                    return;
                end
                if ~isempty(m)
                    msg = m;
                end
            end

            if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef) && isfield(runObj.pipelineRef,'id') ...
                    && ~isempty(runObj.pipelineRef.id)
                vars = evalin('base','who');
                for vi = 1:numel(vars)
                    try
                        tmp = evalin('base', vars{vi});
                    catch
                        continue;
                    end
                    if isa(tmp,'pipeline') && strcmp(tmp.strid, char(string(runObj.pipelineRef.id)))
                        pipeObj = tmp;
                        msg = '';
                        return;
                    end
                end
            end

            try
                [foundDefault, defaultPipe] = app.getProjectDefaultPipelineObject(shallowObj);
            catch
                foundDefault = false;
                defaultPipe = [];
            end
            if foundDefault && ~isempty(defaultPipe)
                wantId = '';
                try
                    wantId = char(string(runObj.pipelineRef.id));
                catch
                end
                if isempty(wantId) || strcmp(defaultPipe.strid, wantId)
                    pipeObj = defaultPipe;
                    msg = '';
                    return;
                end
            end
        end

        function [runObj, changed] = backfillRunPipelineRef(app, runObj, pipeObj, shallowObj)
            changed = false;
            if nargin < 2 || isempty(runObj)
                return;
            end

            resolvedPath = '';
            try
                if isa(pipeObj,'pipeline') && isprop(pipeObj,'path') && ~isempty(pipeObj.path)
                    resolvedPath = char(string(pipeObj.path));
                end
            catch
            end

            if isempty(resolvedPath)
                try
                    defaultJson = app.getProjectDefaultPipelinePath(shallowObj);
                    if ~isempty(defaultJson)
                        resolvedPath = fileparts(defaultJson);
                    end
                catch
                end
            end

            if isempty(resolvedPath)
                return;
            end

            if ~isprop(runObj,'pipelineRef') || isempty(runObj.pipelineRef) || ~isstruct(runObj.pipelineRef)
                runObj.pipelineRef = struct('id','','path','','version','');
                changed = true;
            end
            if ~isfield(runObj.pipelineRef,'path') || ~strcmp(char(string(runObj.pipelineRef.path)), resolvedPath)
                runObj.pipelineRef.path = resolvedPath;
                changed = true;
            end
            if isprop(runObj,'templatePath') && ~strcmp(char(string(runObj.templatePath)), resolvedPath)
                runObj.templatePath = resolvedPath;
                changed = true;
            end
            if isprop(runObj,'templateId') && isempty(runObj.templateId) && isfield(runObj.pipelineRef,'id')
                runObj.templateId = runObj.pipelineRef.id;
                changed = true;
            end
            if isstruct(runObj.ctx)
                if ~isfield(runObj.ctx,'pipelineRef') || ~isstruct(runObj.ctx.pipelineRef)
                    runObj.ctx.pipelineRef = runObj.pipelineRef;
                    changed = true;
                elseif ~isfield(runObj.ctx.pipelineRef,'path') || ~strcmp(char(string(runObj.ctx.pipelineRef.path)), resolvedPath)
                    runObj.ctx.pipelineRef.path = resolvedPath;
                    changed = true;
                end
            end
        end

        function n = getProjectFovCount(app, shallowObj) %#ok<INUSD>
            n = 0;
            if isempty(shallowObj) || ~isa(shallowObj,'shallow')
                return;
            end
            if isempty(shallowObj.fov)
                return;
            end
            if numel(shallowObj.fov)==1
                try
                    if isempty(shallowObj.fov(1).srcpath) || isempty(shallowObj.fov(1).srcpath{1})
                        return;
                    end
                catch
                    return;
                end
            end
            n = numel(shallowObj.fov);
        end

        function [isLoaded, pipeObj, pipeVar] = getLoadedPipelineForNode(app, node)
            isLoaded = false;
            pipeObj = [];
            pipeVar = '';
            if nargin < 2 || isempty(node) || ~isprop(node,'Tag') || ~strcmp(node.Tag,'Pipeline')
                return;
            end

            idx = [];
            try
                ud = node.UserData;
                if isnumeric(ud) && ~isempty(ud)
                    idx = ud(1);
                elseif isstruct(ud)
                    if isfield(ud,'pipeIdx') && ~isempty(ud.pipeIdx)
                        idx = ud.pipeIdx;
                    elseif isfield(ud,'idx') && ~isempty(ud.idx)
                        idx = ud.idx;
                    end
                    if isfield(ud,'varName') && ~isempty(ud.varName)
                        pipeVar = char(string(ud.varName));
                    elseif isfield(ud,'workspaceVar') && ~isempty(ud.workspaceVar)
                        pipeVar = char(string(ud.workspaceVar));
                    end
                end
            catch
                idx = [];
            end

            if isempty(pipeVar) && ~isempty(idx) && idx >= 1 && idx <= numel(app.Data.Pipeline)
                pipeVar = app.Data.Pipeline{idx};
            end
            if isempty(pipeVar)
                return;
            end

            try
                candidate = evalin('base', pipeVar);
                if isa(candidate,'pipeline')
                    pipeObj = candidate;
                    isLoaded = true;
                end
            catch
                pipeObj = [];
                isLoaded = false;
            end
        end

        function jsonPath = resolvePipelineJsonPathForNode(app, node)
            jsonPath = '';
            if nargin < 2 || isempty(node) || ~isprop(node,'Tag') || ~strcmp(node.Tag,'Pipeline')
                return;
            end

            [isLoaded, pipeObj] = app.getLoadedPipelineForNode(node);
            if isLoaded && isa(pipeObj,'pipeline') && isprop(pipeObj,'path') && ~isempty(pipeObj.path)
                jsonPath = fullfile(char(string(pipeObj.path)), 'pipeline.json');
                return;
            end

            try
                ud = node.UserData;
                if isstruct(ud)
                    if isfield(ud,'jsonPath') && ~isempty(ud.jsonPath)
                        jsonPath = char(string(ud.jsonPath));
                        return;
                    end
                    if isfield(ud,'pipelineJsonPath') && ~isempty(ud.pipelineJsonPath)
                        jsonPath = char(string(ud.pipelineJsonPath));
                        return;
                    end
                    if isfield(ud,'path') && ~isempty(ud.path)
                        candidatePath = char(string(ud.path));
                        if isfolder(candidatePath)
                            jsonPath = fullfile(candidatePath, 'pipeline.json');
                        else
                            jsonPath = candidatePath;
                        end
                        return;
                    end
                end
            catch
            end

            projectNode = [];
            try
                parentNode = node.Parent;
                if ~isempty(parentNode) && isprop(parentNode,'Tag') && strcmp(parentNode.Tag,'Project')
                    projectNode = parentNode;
                end
            catch
                projectNode = [];
            end
            if isempty(projectNode)
                return;
            end

            projIdx = [];
            try
                projIdx = projectNode.UserData;
            catch
                projIdx = [];
            end
            if isempty(projIdx) || projIdx < 1 || projIdx > numel(app.Data.Project)
                return;
            end

            try
                shallowObj = evalin('base', app.Data.Project{projIdx});
            catch
                shallowObj = [];
            end
            if isempty(shallowObj) || ~isa(shallowObj,'shallow')
                return;
            end

            preferred = app.getProjectDefaultPipelinePath(shallowObj);
            candidates = app.resolveProjectPipelineJsonCandidates(shallowObj);
            nodeLabel = char(string(node.Text));

            if ~isempty(preferred)
                try
                    [prefParent,~,~] = fileparts(preferred);
                    [~,prefFolder] = fileparts(prefParent);
                    if strcmp(prefFolder, nodeLabel)
                        jsonPath = preferred;
                        return;
                    end
                catch
                end
            end

            for iCand = 1:numel(candidates)
                candPath = char(string(candidates{iCand}));
                try
                    [candParent,~,~] = fileparts(candPath);
                    [~,candFolder] = fileparts(candParent);
                    if strcmp(candFolder, nodeLabel)
                        jsonPath = candPath;
                        return;
                    end
                catch
                end
            end

            if numel(candidates) == 1
                jsonPath = char(string(candidates{1}));
            elseif ~isempty(preferred)
                jsonPath = preferred;
            end
        end

        function [loaded, pipeObj, varName, jsonPath, msg] = loadPipelineForNode(app, node)
            loaded = false;
            pipeObj = [];
            varName = '';
            msg = '';
            jsonPath = app.resolvePipelineJsonPathForNode(node);

            if isempty(jsonPath)
                msg = 'Unable to resolve pipeline.json for the selected pipeline.';
                return;
            end
            if ~isfile(jsonPath)
                msg = sprintf('Pipeline not found:\n%s', jsonPath);
                return;
            end

            [pipeObj, msg] = pipelineLoad(jsonPath);
            if isempty(pipeObj)
                return;
            end

            varName = app.nextPipelineVarName(pipeObj);
            assignin('base', varName, pipeObj);
            loaded = true;

            try
                app.registerRecentPipeline(string(jsonPath));
            catch
            end
        end

        function selectPipelineNodeByJsonPath(app, jsonPath)
            if isempty(jsonPath)
                return;
            end

            targetKey = app.normalizeFsPath(jsonPath);
            if isempty(targetKey)
                return;
            end

            node = findNodeRecursive(app.Tree);
            if isempty(node)
                return;
            end

            try
                app.Tree.SelectedNodes = node;
            catch
            end

            function hit = findNodeRecursive(parentNode)
                hit = [];
                try
                    children = parentNode.Children;
                catch
                    children = [];
                end

                for iChild = 1:numel(children)
                    child = children(iChild);
                    try
                        if isprop(child,'Tag') && strcmp(child.Tag,'Pipeline')
                            childPath = app.resolvePipelineJsonPathForNode(child);
                            if strcmp(app.normalizeFsPath(childPath), targetKey)
                                hit = child;
                                return;
                            end
                        end
                    catch
                    end

                    hit = findNodeRecursive(child);
                    if ~isempty(hit)
                        return;
                    end
                end
            end
        end

        function pipeObj = ensureDefaultPipelineForProject(app, shallowObj)
            pipeObj = [];
            if isempty(shallowObj) || ~isa(shallowObj,'shallow')
                return;
            end
            if isempty(shallowObj.io.path) || isempty(shallowObj.io.file)
                return;
            end

            defaultPath = app.getProjectDefaultPipelinePath(shallowObj);
            candidateJson = defaultPath;
            if isempty(candidateJson)
                candidates = app.resolveProjectPipelineJsonCandidates(shallowObj);
                if ~isempty(candidates)
                    candidateJson = candidates{1};
                end
            end

            if ~isempty(candidateJson)
                [pipeObj,msg] = pipelineLoad(candidateJson);
                if isempty(pipeObj)
                    warning('detecdiv:defaultPipeline', 'Cannot load existing pipeline template: %s', msg);
                    return;
                end
            else
                projectRoot = fullfile(char(string(shallowObj.io.path)), char(string(shallowObj.io.file)));
                if ~exist(projectRoot,'dir')
                    return;
                end

                pipeName = [char(string(shallowObj.io.file)) '_pipeline'];
                try
                    pipeObj = pipelineNew('path', projectRoot, 'name', pipeName, 'workspace', false);
                catch ME
                    warning('detecdiv:defaultPipeline', 'Cannot create default pipeline: %s', ME.message);
                    return;
                end
                if isempty(pipeObj)
                    return;
                end
            end

            rawPath = app.deriveRawDataPathFromProject(shallowObj);
            changed = false;
            if ~isempty(rawPath)
                changed = app.populateDefaultDataLoaderPath(pipeObj, rawPath) || changed;
            end
            changed = app.setProjectDefaultPipelineRef(shallowObj, pipeObj) || changed;

            if changed
                try
                    pipelineSave(pipeObj);
                    app.publishPipelineObjectToWorkspace(pipeObj);
                catch
                end
                try
                    app.saveShallowProjectWithProgress(shallowObj, 'Saving default project pipeline...', 'shallowObj');
                catch
                end
            end

            existingPaths = app.listLoadedPipelinePaths();
            key = app.normalizeFsPath(pipeObj.path);
            if ~isempty(key) && isKey(existingPaths, key)
                return;
            end

            varName = app.nextPipelineVarName(pipeObj);
            assignin('base', varName, pipeObj);

            try
                app.registerRecentPipeline(string(fullfile(pipeObj.path,'pipeline.json')));
            catch
            end
        end

        function openProjectPipelineTemplate(app, shallowObj)
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end
            pipeObj = app.ensureDefaultPipelineForProject(shallowObj);
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                [found, pipeObj] = app.getProjectDefaultPipelineObject(shallowObj);
                if ~found
                    uialert(app.DetecDivUIFigure, 'No pipeline template could be resolved for this project.', 'Open project pipeline', 'Icon', 'warning');
                    return;
                end
            end
            app.openPipelineWithContext(pipeObj);
        end

        function createProjectPipelineTemplate(app, shallowObj)
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end
            projectRoot = fullfile(char(string(shallowObj.io.path)), char(string(shallowObj.io.file)));
            if isempty(projectRoot) || exist(projectRoot, 'dir') ~= 7
                uialert(app.DetecDivUIFigure, 'Project folder is not available; cannot create a project pipeline template.', 'New pipeline template', 'Icon', 'warning');
                return;
            end

            defaultName = [char(string(shallowObj.io.file)) '_pipeline'];
            answer = inputdlg({'Pipeline template name:'}, 'New pipeline template', [1 60], {defaultName});
            if isempty(answer)
                return;
            end
            pipeName = strtrim(answer{1});
            if isempty(pipeName)
                pipeName = defaultName;
            end

            try
                pipeObj = pipelineNew('path', projectRoot, 'name', pipeName, 'workspace', true);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'New pipeline template', 'Icon', 'error');
                return;
            end
            if isempty(pipeObj)
                return;
            end

            try
                rawPath = app.deriveRawDataPathFromProject(shallowObj);
                if ~isempty(rawPath)
                    app.populateDefaultDataLoaderPath(pipeObj, rawPath);
                end
                app.setProjectDefaultPipelineRef(shallowObj, pipeObj);
                pipelineSave(pipeObj);
                app.publishPipelineObjectToWorkspace(pipeObj);
                app.saveShallowProjectWithProgress(shallowObj, 'Saving project pipeline template...', 'shallowObj');
            catch ME
                warning('detecdiv:ProjectPipelineTemplate', '%s', ME.message);
            end

            app.registerRecentPipeline(string(fullfile(pipeObj.path, 'pipeline.json')));
            app.openPipelineWithContext(pipeObj);
        end

        function openLatestProjectRunOrPipeline(app, shallowObj)
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end
            runObj = app.latestProjectPipelineRun(shallowObj);
            if ~isempty(runObj) && isa(runObj, 'pipelineRun')
                app.openPipelineEditorWithProgress(shallowObj, runObj, 'Opening pipeline run editor...');
                return;
            end
            app.openProjectPipelineTemplate(shallowObj);
        end

        function runObj = latestProjectPipelineRun(app, shallowObj) %#ok<INUSD>
            runObj = [];
            try
                runs = shallowObj.processing.pipelineRun;
            catch
                runs = pipelineRun.empty;
            end
            if isempty(runs)
                return;
            end
            bestScore = -inf;
            bestIdx = 0;
            for i = 1:numel(runs)
                score = i;
                try
                    txt = '';
                    if isprop(runs(i), 'updatedAt') && ~isempty(runs(i).updatedAt)
                        txt = char(string(runs(i).updatedAt));
                    elseif isprop(runs(i), 'createdAt') && ~isempty(runs(i).createdAt)
                        txt = char(string(runs(i).createdAt));
                    end
                    if ~isempty(txt)
                        dt = datetime(txt);
                        if ~isnat(dt)
                            score = datenum(dt);
                        end
                    end
                catch
                end
                if score >= bestScore
                    bestScore = score;
                    bestIdx = i;
                end
            end
            if bestIdx > 0
                runObj = runs(bestIdx);
            end
        end

        function rawPath = deriveRawDataPathFromProject(app, shallowObj) %#ok<INUSD>
            rawPath = '';

            try
                if isprop(shallowObj,'runProfiles') && isfield(shallowObj.runProfiles,'dataloading')
                    dl = shallowObj.runProfiles.dataloading;
                    if isfield(dl,'dataLoader') && isstruct(dl.dataLoader)
                        if isfield(dl.dataLoader,'path') && ~isempty(dl.dataLoader.path)
                            rawPath = char(string(dl.dataLoader.path));
                            if exist(rawPath,'dir')
                                return;
                            end
                            rawPath = '';
                        end
                    end
                end
            catch
                rawPath = '';
            end

            try
                if ~isempty(shallowObj.fov) && numel(shallowObj.fov)>=1
                    src = shallowObj.fov(1).srcpath;
                    if iscell(src) && ~isempty(src) && ~isempty(src{1})
                        rawPath = char(string(src{1}));
                        if exist(rawPath,'dir')
                            return;
                        end
                    end
                end
            catch
                rawPath = '';
            end
        end

        function changed = populateDefaultDataLoaderPath(app, pipeObj, rawPath) %#ok<INUSD>
            changed = false;
            if isempty(pipeObj) || ~isa(pipeObj,'pipeline')
                return;
            end
            if isempty(pipeObj.nodes)
                return;
            end
            if isempty(rawPath)
                return;
            end

            for iNode = 1:numel(pipeObj.nodes)
                n = pipeObj.nodes(iNode);
                if ~isfield(n,'type') || ~strcmpi(char(string(n.type)),'dataloader')
                    continue;
                end

                if ~isfield(n,'params') || ~isstruct(n.params)
                    n.params = struct();
                end

                hasPath = isfield(n.params,'path') && ~isempty(n.params.path);
                if ~hasPath || ~strcmp(char(string(n.params.path)), char(string(rawPath)))
                    n.params.path = rawPath;
                    pipeObj.nodes(iNode) = n;
                    changed = true;
                end
            end
        end

        function publishPipelineObjectToWorkspace(app, pipeObj)
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end

            targetKey = app.normalizeFsPath(pipeObj.path);
            if isempty(targetKey)
                return;
            end

            try
                vars = evalin('base', 'who');
            catch
                vars = {};
            end

            for iVar = 1:numel(vars)
                vname = vars{iVar};
                try
                    obj = evalin('base', vname);
                catch
                    continue;
                end
                if ~isa(obj, 'pipeline') || ~isprop(obj, 'path')
                    continue;
                end
                if strcmp(app.normalizeFsPath(obj.path), targetKey)
                    try
                        assignin('base', vname, pipeObj);
                    catch
                    end
                    return;
                end
            end

            try
                assignin('base', app.nextPipelineVarName(pipeObj), pipeObj);
            catch
            end
        end

        function defaultPath = getProjectDefaultPipelinePath(app, shallowObj) %#ok<INUSD>
            configuredPath = '';
            try
                if isprop(shallowObj,'runProfiles') && ~isempty(shallowObj.runProfiles) ...
                        && isfield(shallowObj.runProfiles,'pipeline') && ~isempty(shallowObj.runProfiles.pipeline)
                    p = shallowObj.runProfiles.pipeline;
                    if isfield(p,'defaultTemplatePath') && ~isempty(p.defaultTemplatePath)
                        configuredPath = char(string(p.defaultTemplatePath));
                    end
                end
            catch
                configuredPath = '';
            end

            defaultPath = app.resolveProjectPreferredPipelineJson(shallowObj, configuredPath);
        end

        function [found, pipeObj] = getProjectDefaultPipelineObject(app, shallowObj)
            found = false;
            pipeObj = [];

            jsonPath = app.getProjectDefaultPipelinePath(shallowObj);
            if isempty(jsonPath)
                return;
            end
            targetKey = app.normalizePipelineRootPath(jsonPath);

            try
                vars = evalin('base', 'who');
            catch
                vars = {};
            end

            for iVar = 1:numel(vars)
                try
                    obj = evalin('base', vars{iVar});
                catch
                    continue;
                end
                if ~isa(obj, 'pipeline')
                    continue;
                end
                thisKey = app.normalizePipelineRootPath(obj.path);
                if ~isempty(thisKey) && strcmp(thisKey, targetKey)
                    found = true;
                    pipeObj = obj;
                    return;
                end
            end

            [pipeObj, msg] = pipelineLoad(jsonPath);
            if isempty(pipeObj)
                if ~isempty(msg)
                    warning('detecdiv:PipelineLoad', '%s', msg);
                end
                return;
            end

            found = true;
            try
                assignin('base', pipeObj.strid, pipeObj);
            catch
            end
        end

        function changed = ensureProjectDefaultPipelineNode(app, shallowObj, nodeType, params)
            changed = false;
            if nargin < 4 || isempty(params) || ~isstruct(params)
                params = struct();
            end
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            pipeObj = app.ensureDefaultPipelineForProject(shallowObj);
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                [found, pipeObj] = app.getProjectDefaultPipelineObject(shallowObj);
                if ~found || isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                    return;
                end
            end

            targetType = lower(char(string(nodeType)));
            for iNode = 1:numel(pipeObj.nodes)
                node = pipeObj.nodes(iNode);
                if isfield(node, 'type') && strcmpi(char(string(node.type)), targetType)
                    return;
                end
            end

            switch targetType
                case 'roiextract'
                    defaults = roiExtract.setparam(struct());
                    node = struct( ...
                        'id', 'roiextract_1', ...
                        'name', 'roiextract_1', ...
                        'type', 'roiExtract', ...
                        'func', 'roiExtract.process', ...
                        'gui', 'roiExtract.ui', ...
                        'guiMode', 'replace', ...
                        'paramRequired', {{}}, ...
                        'pkg', '', ...
                        'params', defaults, ...
                        'inputs', {{'roiList'}}, ...
                        'outputs', {{'roiList','channels'}}, ...
                        'enabled', true, ...
                        'status', '', ...
                        'layout', [70 10 20 10]);
                otherwise
                    return;
            end

            if ~isempty(fieldnames(params))
                node.params = params;
            end

            nodes = pipeObj.nodes;
            insertIdx = numel(nodes) + 1;
            roiTypes = {'roiidentify','roipattern','roimanual','roigrid','roitracked'};
            for iNode = 1:numel(nodes)
                t = '';
                if isfield(nodes(iNode), 'type')
                    t = lower(char(string(nodes(iNode).type)));
                end
                if any(strcmp(t, roiTypes))
                    insertIdx = iNode + 1;
                end
            end

            if insertIdx > numel(nodes)
                nodes(end+1) = node; %#ok<AGROW>
            else
                nodes = [nodes(1:insertIdx-1) node nodes(insertIdx:end)]; %#ok<AGROW>
            end
            pipeObj.nodes = nodes;

            roiId = '';
            for iNode = 1:numel(nodes)
                t = '';
                if isfield(nodes(iNode), 'type')
                    t = lower(char(string(nodes(iNode).type)));
                end
                if any(strcmp(t, roiTypes))
                    roiId = char(string(nodes(iNode).id));
                end
            end
            if ~isempty(roiId)
                pipeObj.edges(end+1) = struct('from', roiId, 'to', node.id, 'fromPort', 'roiList', 'toPort', 'roiList', 'condition', ''); %#ok<AGROW>
            end

            try
                pipelineSave(pipeObj);
                app.publishPipelineObjectToWorkspace(pipeObj);
                changed = true;
            catch ME
                warning('detecdiv:PipelineSave', '%s', ME.message);
            end
        end

        function changed = updateProjectDefaultPipelineNodeParams(app, shallowObj, nodeType, params)
            changed = false;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            [found, pipeObj] = app.getProjectDefaultPipelineObject(shallowObj);
            if ~found || isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end

            for iNode = 1:numel(pipeObj.nodes)
                node = pipeObj.nodes(iNode);
                if ~isfield(node, 'type') || ~strcmpi(char(string(node.type)), char(string(nodeType)))
                    continue;
                end
                node.params = params;
                pipeObj.nodes(iNode) = node;
                changed = true;
                break;
            end

            if changed
                try
                    pipelineSave(pipeObj);
                    app.publishPipelineObjectToWorkspace(pipeObj);
                catch ME
                    warning('detecdiv:PipelineSave', '%s', ME.message);
                end
            end
        end

        function changed = replaceProjectDefaultRoiProducerNode(app, shallowObj, nodeType, params)
            changed = false;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            if strcmpi(char(string(nodeType)), 'roiTracked')
                changed = app.ensureProjectTrackedPipelineNode(shallowObj, params);
                return;
            end

            pipeObj = app.ensureDefaultPipelineForProject(shallowObj);
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                [found, pipeObj] = app.getProjectDefaultPipelineObject(shallowObj);
                if ~found || isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                    return;
                end
            end

            roiTypes = {'roiidentify','roipattern','roigrid','roimanual','roitracked'};
            modeName = lower(char(string(nodeType)));
            switch modeName
                case {'roiidentify','roipattern'}
                    workflowMode = 'pattern';
                case 'roigrid'
                    workflowMode = 'grid';
                otherwise
                    workflowMode = modeName;
            end

            nodes = pipeObj.nodes;
            roiIdx = [];
            roiOldIds = {};
            roiLayout = [40 10 20 10];
            for iNode = 1:numel(nodes)
                t = '';
                if isfield(nodes(iNode), 'type')
                    t = lower(char(string(nodes(iNode).type)));
                end
                if any(strcmp(t, roiTypes))
                    roiIdx(end+1) = iNode; %#ok<AGROW>
                    roiOldIds{end+1} = char(string(nodes(iNode).id)); %#ok<AGROW>
                    if isfield(nodes(iNode), 'layout') && ~isempty(nodes(iNode).layout)
                        roiLayout = nodes(iNode).layout;
                    end
                end
            end

            newNode = app.buildDefaultRoiProducerNode(nodeType, params, roiLayout);
            if isempty(newNode)
                return;
            end

            if isempty(roiIdx)
                insertIdx = numel(nodes) + 1;
                for iNode = 1:numel(nodes)
                    t = '';
                    if isfield(nodes(iNode), 'type')
                        t = lower(char(string(nodes(iNode).type)));
                    end
                    if strcmp(t, 'roiextract')
                        insertIdx = iNode;
                        break;
                    end
                end
                if insertIdx > numel(nodes)
                    nodes(end+1) = newNode; %#ok<AGROW>
                else
                    nodes = [nodes(1:insertIdx-1) newNode nodes(insertIdx:end)]; %#ok<AGROW>
                end
            else
                insertIdx = roiIdx(1);
                keepMask = true(1, numel(nodes));
                keepMask(roiIdx) = false;
                keptNodes = nodes(keepMask);
                if insertIdx <= 1
                    nodes = [newNode keptNodes]; %#ok<AGROW>
                elseif insertIdx > numel(keptNodes)
                    nodes = [keptNodes newNode]; %#ok<AGROW>
                else
                    nodes = [keptNodes(1:insertIdx-1) newNode keptNodes(insertIdx:end)]; %#ok<AGROW>
                end
            end

            pipeObj.nodes = nodes;

            if strcmpi(newNode.type, 'roiTracked')
                [pipeObj.nodes, ~] = app.ensureTrackedMaskSupportNodesInTemplate(pipeObj.nodes);
                nodes = pipeObj.nodes;
            end

            normEdges = struct('from',{},'to',{},'fromPort',{},'toPort',{},'condition',{});
            roiIds = {newNode.id};
            if ~isempty(roiOldIds)
                roiIds = [roiIds roiOldIds]; %#ok<AGROW>
            end
            for iNode = 1:numel(nodes)
                t = '';
                if isfield(nodes(iNode), 'type')
                    t = lower(char(string(nodes(iNode).type)));
                end
                if any(strcmp(t, roiTypes))
                    idVal = char(string(nodes(iNode).id));
                    if ~any(strcmp(roiIds, idVal))
                        roiIds{end+1} = idVal; %#ok<AGROW>
                    end
                end
            end

            for iEdge = 1:numel(pipeObj.edges)
                e = pipeObj.edges(iEdge);
                fromId = '';
                toId = '';
                if isfield(e, 'from'), fromId = char(string(e.from)); end
                if isfield(e, 'to'), toId = char(string(e.to)); end
                if any(strcmp(roiIds, fromId)) || any(strcmp(roiIds, toId))
                    continue;
                end
                normEdges(end+1) = e; %#ok<AGROW>
            end

            loaderId = '';
            extractId = '';
            maskSourceId = '';
            for iNode = 1:numel(nodes)
                t = '';
                if isfield(nodes(iNode), 'type')
                    t = lower(char(string(nodes(iNode).type)));
                end
                if strcmp(t, 'dataloader') && isempty(loaderId)
                    loaderId = char(string(nodes(iNode).id));
                elseif strcmp(t, 'roiextract') && isempty(extractId)
                    extractId = char(string(nodes(iNode).id));
                elseif strcmp(t, 'classifier') && isempty(maskSourceId)
                    outs = {};
                    if isfield(nodes(iNode),'outputs') && ~isempty(nodes(iNode).outputs)
                        outs = lower(cellstr(nodes(iNode).outputs(:)));
                    end
                    if isempty(outs)
                        try
                            ctr = pipelineNodeContract(nodes(iNode));
                            if isfield(ctr,'out') && ~isempty(ctr.out)
                                outs = lower({ctr.out.name});
                            end
                        catch
                        end
                    end
                    if any(strcmp(outs,'masks'))
                        maskSourceId = char(string(nodes(iNode).id));
                    end
                end
            end

            if strcmpi(newNode.type, 'roiTracked') && ~isempty(maskSourceId) && ~isempty(extractId)
                if ~isempty(loaderId)
                    normEdges(end+1) = struct('from', loaderId, 'to', newNode.id, 'fromPort', 'images', 'toPort', 'images', 'condition', ''); %#ok<AGROW>
                end
                normEdges(end+1) = struct('from', newNode.id, 'to', extractId, 'fromPort', 'roiList', 'toPort', 'roiList', 'condition', ''); %#ok<AGROW>
                normEdges(end+1) = struct('from', extractId, 'to', maskSourceId, 'fromPort', 'roiList', 'toPort', 'roiList', 'condition', ''); %#ok<AGROW>
                normEdges(end+1) = struct('from', newNode.id, 'to', maskSourceId, 'fromPort', 'roiList', 'toPort', 'roiList', 'condition', ''); %#ok<AGROW>
                normEdges(end+1) = struct('from', maskSourceId, 'to', newNode.id, 'fromPort', 'masks', 'toPort', 'masks', 'condition', ''); %#ok<AGROW>
            else
                if ~isempty(loaderId)
                    normEdges(end+1) = struct('from', loaderId, 'to', newNode.id, 'fromPort', 'images', 'toPort', 'images', 'condition', ''); %#ok<AGROW>
                end
                if strcmpi(newNode.type, 'roiTracked') && ~isempty(maskSourceId)
                    normEdges(end+1) = struct('from', maskSourceId, 'to', newNode.id, 'fromPort', 'masks', 'toPort', 'masks', 'condition', ''); %#ok<AGROW>
                end
                if ~isempty(extractId)
                    normEdges(end+1) = struct('from', newNode.id, 'to', extractId, 'fromPort', 'roiList', 'toPort', 'roiList', 'condition', ''); %#ok<AGROW>
                end
            end
            pipeObj.edges = normEdges;

            if ~isfield(pipeObj.runProfiles, 'roiWorkflow') || ~isstruct(pipeObj.runProfiles.roiWorkflow)
                pipeObj.runProfiles.roiWorkflow = struct();
            end
            pipeObj.runProfiles.roiWorkflow.mode = workflowMode;

            try
                pipelineSave(pipeObj);
                app.publishPipelineObjectToWorkspace(pipeObj);
                changed = true;
            catch ME
                warning('detecdiv:PipelineSave', '%s', ME.message);
            end
        end

        function [nodes, changed] = ensureTrackedMaskSupportNodesInTemplate(app, nodes)
            changed = false;
            if nargin < 2 || isempty(nodes)
                nodes = struct([]);
            end

            exIdx = app.findNodeIndexInTemplate(nodes, 'roiExtract');
            if isempty(exIdx)
                exNode = app.buildDefaultTemplateExtractNode();
                insertPos = numel(nodes) + 1;
                for iNode = 1:numel(nodes)
                    t = '';
                    if isfield(nodes(iNode),'type')
                        t = lower(char(string(nodes(iNode).type)));
                    end
                    if any(strcmp(t, {'roiidentify','roipattern','roimanual','roigrid','roitracked'}))
                        insertPos = iNode + 1;
                    end
                end
                nodes = [nodes(1:insertPos-1) exNode nodes(insertPos:end)]; %#ok<AGROW>
                changed = true;
                exIdx = app.findNodeIndexInTemplate(nodes, 'roiExtract');
            end

            maskIdx = app.findMaskClassifierNodeIndexInTemplate(nodes);
            if isempty(maskIdx)
                maskNode = app.buildDefaultMaskClassifierTemplateNode();
                if isempty(exIdx)
                    insertPos = numel(nodes) + 1;
                else
                    insertPos = exIdx + 1;
                end
                nodes = [nodes(1:insertPos-1) maskNode nodes(insertPos:end)]; %#ok<AGROW>
                changed = true;
            end
        end

        function idx = findNodeIndexInTemplate(app, nodes, typeName) %#ok<INUSD>
            idx = [];
            for iNode = 1:numel(nodes)
                if isfield(nodes(iNode),'type') && strcmpi(char(string(nodes(iNode).type)), char(string(typeName)))
                    idx = iNode;
                    return;
                end
            end
        end

        function idx = findMaskClassifierNodeIndexInTemplate(app, nodes)
            idx = [];
            for iNode = 1:numel(nodes)
                if ~isfield(nodes(iNode),'type') || ~strcmpi(char(string(nodes(iNode).type)), 'classifier')
                    continue;
                end
                if app.templateNodeProducesMasks(nodes(iNode))
                    idx = iNode;
                    return;
                end
            end
        end

        function tf = templateNodeProducesMasks(app, node) %#ok<INUSD>
            tf = false;
            outs = {};
            if isfield(node,'outputs') && ~isempty(node.outputs)
                outs = lower(cellstr(node.outputs(:)));
            end
            if isempty(outs)
                try
                    ctr = pipelineNodeContract(node);
                    if isfield(ctr,'out') && ~isempty(ctr.out)
                        outs = lower({ctr.out.name});
                    end
                catch
                    outs = {};
                end
            end
            tf = any(strcmp(outs,'masks'));
        end

        function node = buildDefaultTemplateExtractNode(app) %#ok<INUSD>
            defaults = roiExtract.setparam(struct());
            node = struct('id','roiextract_1','name','roiextract_1','type','roiExtract','func','roiExtract.process','gui','roiExtract.ui','guiMode','replace','paramRequired',{{}},'pkg','','params',defaults,'inputs',{{'roiList'}},'outputs',{{'roiList','channels'}},'enabled',true,'status','','layout',[70 10 20 10]);
        end

        function node = buildDefaultMaskClassifierTemplateNode(app) %#ok<INUSD>
            params = struct('pkg','cellposesam','outputName','tracked_masks');
            node = struct('id','classifier_mask_1','name','classifier_mask_1','type','classifier','func','cellposesam.classify','gui','classifierGUI','guiMode','replace','paramRequired',{{'pkg'}},'pkg','cellposesam','params',params,'inputs',{{'roiList'}},'outputs',{{'roiList','masks'}},'enabled',true,'status','','layout',[95 10 24 10]);
        end

        function node = buildDefaultRoiProducerNode(app, nodeType, params, layout) %#ok<INUSD>
            node = [];
            if nargin < 4 || isempty(layout)
                layout = [40 10 20 10];
            end

            t = lower(char(string(nodeType)));
            switch t
                case {'roiidentify','roipattern'}
                    defaults = roiPattern.setparam(struct());
                    node = struct( ...
                        'id', 'roipattern_1', ...
                        'name', 'roipattern_1', ...
                        'type', 'roiPattern', ...
                        'func', 'roiPattern.process', ...
                        'gui', 'roiPattern.ui', ...
                        'guiMode', 'replace', ...
                        'paramRequired', {{}}, ...
                        'pkg', '', ...
                        'params', defaults, ...
                        'inputs', {{'images'}}, ...
                        'outputs', {{'roiList'}}, ...
                        'enabled', true, ...
                        'status', '', ...
                        'layout', layout);
                case 'roimanual'
                    defaults = roiManual.setparam(struct());
                    node = struct( ...
                        'id', 'roimanual_1', ...
                        'name', 'roimanual_1', ...
                        'type', 'roiManual', ...
                        'func', 'roiManual.process', ...
                        'gui', 'roiManual.ui', ...
                        'guiMode', 'replace', ...
                        'paramRequired', {{}}, ...
                        'pkg', '', ...
                        'params', defaults, ...
                        'inputs', {{'images'}}, ...
                        'outputs', {{'roiList'}}, ...
                        'enabled', true, ...
                        'status', '', ...
                        'layout', layout);
                case 'roigrid'
                    defaults = roiGrid.setparam(struct());
                    node = struct( ...
                        'id', 'roigrid_1', ...
                        'name', 'roigrid_1', ...
                        'type', 'roiGrid', ...
                        'func', 'roiGrid.process', ...
                        'gui', 'roiGrid.ui', ...
                        'guiMode', 'replace', ...
                        'paramRequired', {{}}, ...
                        'pkg', '', ...
                        'params', defaults, ...
                        'inputs', {{'images'}}, ...
                        'outputs', {{'roiList'}}, ...
                        'enabled', true, ...
                        'status', '', ...
                        'layout', layout);
                case 'roitracked'
                    defaults = roiTracked.setparam(struct());
                    node = struct( ...
                        'id', 'roitracked_1', ...
                        'name', 'roitracked_1', ...
                        'type', 'roiTracked', ...
                        'func', 'roiTracked.process', ...
                        'gui', 'roiTracked.ui', ...
                        'guiMode', 'replace', ...
                        'paramRequired', {{}}, ...
                        'pkg', '', ...
                        'params', defaults, ...
                        'inputs', {{'roiList','masks'}}, ...
                        'outputs', {{'roiList'}}, ...
                        'enabled', true, ...
                        'status', '', ...
                        'layout', layout);
                otherwise
                    return;
            end

            if nargin >= 3 && isstruct(params) && ~isempty(params)
                node.params = params;
            end
        end

        function baseType = getTrackedBaseRoiType(app, pipeObj) %#ok<INUSD>
            baseType = '';
            fallbackType = '';
            for iNode = 1:numel(pipeObj.nodes)
                node = pipeObj.nodes(iNode);
                if ~isfield(node,'type')
                    continue;
                end
                t = lower(char(string(node.type)));
                if ~any(strcmp(t, {'roipattern','roiidentify','roimanual','roigrid'}))
                    continue;
                end
                canon = t;
                if any(strcmp(t, {'roipattern','roiidentify'}))
                    canon = 'roipattern';
                end
                if isempty(fallbackType)
                    fallbackType = canon;
                end
                enabled = true;
                if isfield(node,'enabled') && ~isempty(node.enabled)
                    enabled = logical(node.enabled);
                end
                if enabled
                    baseType = canon;
                    return;
                end
            end
            if isempty(baseType)
                if isempty(fallbackType)
                    baseType = 'roipattern';
                else
                    baseType = fallbackType;
                end
            end
        end

        function changed = ensureProjectTrackedPipelineNode(app, shallowObj, params)
            changed = false;
            if nargin < 3 || isempty(params) || ~isstruct(params)
                params = struct();
            end
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            pipeObj = app.ensureDefaultPipelineForProject(shallowObj);
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                [found, pipeObj] = app.getProjectDefaultPipelineObject(shallowObj);
                if ~found || isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                    return;
                end
            end

            nodes = pipeObj.nodes;
            sourceType = app.getTrackedBaseRoiType(pipeObj);

            if isempty(app.findNodeIndexInTemplate(nodes, sourceType))
                srcNode = app.buildDefaultRoiProducerNode(sourceType, struct(), [40 10 20 10]);
                if isempty(nodes)
                    nodes = srcNode;
                else
                    nodes = [srcNode nodes]; %#ok<AGROW>
                end
                changed = true;
            end

            trackedIdx = app.findNodeIndexInTemplate(nodes, 'roiTracked');
            if isempty(trackedIdx)
                trackedNode = app.buildDefaultRoiProducerNode('roiTracked', params, [58 10 20 10]);
                insertPos = numel(nodes) + 1;
                for iNode = 1:numel(nodes)
                    t = '';
                    if isfield(nodes(iNode),'type')
                        t = lower(char(string(nodes(iNode).type)));
                    end
                    if any(strcmp(t, {'roipattern','roiidentify','roimanual','roigrid'}))
                        insertPos = iNode + 1;
                    end
                end
                nodes = [nodes(1:insertPos-1) trackedNode nodes(insertPos:end)]; %#ok<AGROW>
                changed = true;
            elseif ~isempty(fieldnames(params))
                nodes(trackedIdx).params = params;
                changed = true;
            end

            [nodes, supportChanged] = app.ensureTrackedMaskSupportNodesInTemplate(nodes);
            changed = changed || supportChanged;
            pipeObj.nodes = nodes;

            sourceId = '';
            trackedId = '';
            extractId = '';
            maskSourceId = '';
            loaderId = '';
            for iNode = 1:numel(nodes)
                node = nodes(iNode);
                t = '';
                if isfield(node,'type')
                    t = lower(char(string(node.type)));
                end
                if strcmp(t,'dataloader') && isempty(loaderId)
                    loaderId = char(string(node.id));
                elseif any(strcmp(t, {'roipattern','roiidentify','roimanual','roigrid'}))
                    canon = t;
                    if any(strcmp(t, {'roipattern','roiidentify'}))
                        canon = 'roipattern';
                    end
                    enabled = true;
                    if isfield(node,'enabled') && ~isempty(node.enabled)
                        enabled = logical(node.enabled);
                    end
                    if enabled && strcmp(canon, sourceType) && isempty(sourceId)
                        sourceId = char(string(node.id));
                    end
                elseif strcmp(t,'roitracked') && isempty(trackedId)
                    trackedId = char(string(node.id));
                elseif strcmp(t,'roiextract') && isempty(extractId)
                    extractId = char(string(node.id));
                elseif strcmp(t,'classifier') && isempty(maskSourceId) && app.templateNodeProducesMasks(node)
                    maskSourceId = char(string(node.id));
                end
            end

            if isempty(sourceId)
                for iNode = 1:numel(nodes)
                    if isfield(nodes(iNode),'type') && any(strcmpi(char(string(nodes(iNode).type)), {'roiPattern','roiIdentify','roiManual','roiGrid'}))
                        sourceId = char(string(nodes(iNode).id));
                        break;
                    end
                end
            end

            keep = true(1, numel(pipeObj.edges));
            rebuildIds = unique([{sourceId}, {trackedId}, {extractId}, {maskSourceId}, {loaderId}]);
            rebuildIds = rebuildIds(~cellfun(@isempty, rebuildIds));
            for iEdge = 1:numel(pipeObj.edges)
                e = pipeObj.edges(iEdge);
                fromId = '';
                toId = '';
                if isfield(e,'from'), fromId = char(string(e.from)); end
                if isfield(e,'to'), toId = char(string(e.to)); end
                if any(strcmp(rebuildIds, fromId)) || any(strcmp(rebuildIds, toId))
                    keep(iEdge) = false;
                end
            end
            normEdges = pipeObj.edges(keep);

            if ~isempty(loaderId) && ~isempty(sourceId)
                normEdges(end+1) = struct('from', loaderId, 'to', sourceId, 'fromPort', 'images', 'toPort', 'images', 'condition', ''); %#ok<AGROW>
            end
            if ~isempty(sourceId) && ~isempty(extractId)
                normEdges(end+1) = struct('from', sourceId, 'to', extractId, 'fromPort', 'roiList', 'toPort', 'roiList', 'condition', ''); %#ok<AGROW>
            end
            if ~isempty(extractId) && ~isempty(maskSourceId)
                normEdges(end+1) = struct('from', extractId, 'to', maskSourceId, 'fromPort', 'roiList', 'toPort', 'roiList', 'condition', ''); %#ok<AGROW>
            end
            if ~isempty(sourceId) && ~isempty(trackedId)
                normEdges(end+1) = struct('from', sourceId, 'to', trackedId, 'fromPort', 'roiList', 'toPort', 'roiList', 'condition', ''); %#ok<AGROW>
            end
            if ~isempty(maskSourceId) && ~isempty(trackedId)
                normEdges(end+1) = struct('from', maskSourceId, 'to', trackedId, 'fromPort', 'masks', 'toPort', 'masks', 'condition', ''); %#ok<AGROW>
            end
            pipeObj.edges = normEdges;

            if ~isfield(pipeObj.runProfiles, 'roiWorkflow') || ~isstruct(pipeObj.runProfiles.roiWorkflow)
                pipeObj.runProfiles.roiWorkflow = struct();
            end
            pipeObj.runProfiles.roiWorkflow.mode = 'tracked';

            for iNode = 1:numel(pipeObj.nodes)
                if ~isfield(pipeObj.nodes(iNode),'type')
                    continue;
                end
                t = lower(char(string(pipeObj.nodes(iNode).type)));
                if any(strcmp(t, {'roipattern','roiidentify'}))
                    pipeObj.nodes(iNode).enabled = strcmp(sourceType, 'roipattern');
                elseif strcmp(t, 'roimanual')
                    pipeObj.nodes(iNode).enabled = strcmp(sourceType, 'roimanual');
                elseif strcmp(t, 'roigrid')
                    pipeObj.nodes(iNode).enabled = strcmp(sourceType, 'roigrid');
                elseif strcmp(t, 'roitracked')
                    pipeObj.nodes(iNode).enabled = true;
                end
            end

            try
                pipelineSave(pipeObj);
                app.publishPipelineObjectToWorkspace(pipeObj);
                changed = true;
            catch ME
                warning('detecdiv:PipelineSave', '%s', ME.message);
            end
        end

        function changed = setProjectDefaultPipelineRoiMode(app, shallowObj, modeName)
            changed = false;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            modeName = lower(char(string(modeName)));
            if strcmp(modeName, 'tracked')
                changed = app.ensureProjectTrackedPipelineNode(shallowObj, struct()) || changed;
            end

            pipeObj = app.ensureDefaultPipelineForProject(shallowObj);
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                [found, pipeObj] = app.getProjectDefaultPipelineObject(shallowObj);
                if ~found || isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                    return;
                end
            end

            roiNodeChanged = false;
            roiNodeFound = false;
            trackedBaseType = '';
            if strcmp(modeName, 'tracked')
                trackedBaseType = app.getTrackedBaseRoiType(pipeObj);
            end

            for iNode = 1:numel(pipeObj.nodes)
                node = pipeObj.nodes(iNode);
                if ~isfield(node, 'type')
                    continue;
                end
                if ~strcmpi(char(string(node.type)), 'roiidentify') && ~strcmpi(char(string(node.type)), 'roipattern') && ~strcmpi(char(string(node.type)), 'roimanual') && ~strcmpi(char(string(node.type)), 'roigrid') && ~strcmpi(char(string(node.type)), 'roitracked')
                    continue;
                end

                roiNodeFound = true;
                if ~isfield(node, 'params') || ~isstruct(node.params)
                    node.params = struct();
                end

                if strcmpi(char(string(node.type)), 'roiidentify') || strcmpi(char(string(node.type)), 'roiPattern')
                    if strcmp(modeName, 'tracked')
                        newEnabled = strcmp(trackedBaseType, 'roipattern');
                    else
                        newEnabled = strcmp(modeName, 'pattern');
                    end
                elseif strcmpi(char(string(node.type)), 'roiManual')
                    if strcmp(modeName, 'tracked')
                        newEnabled = strcmp(trackedBaseType, 'roimanual');
                    else
                        newEnabled = strcmp(modeName, 'manual');
                    end
                elseif strcmpi(char(string(node.type)), 'roiTracked')
                    newEnabled = strcmp(modeName, 'tracked');
                else
                    if strcmp(modeName, 'tracked')
                        newEnabled = strcmp(trackedBaseType, 'roigrid');
                    else
                        newEnabled = strcmp(modeName, 'grid');
                    end
                end
                if ~isfield(node, 'enabled') || logical(node.enabled) ~= newEnabled
                    node.enabled = newEnabled;
                    roiNodeChanged = true;
                end
                if ~isfield(node.params, 'roiMode') || ~strcmp(char(string(node.params.roiMode)), modeName)
                    node.params.roiMode = modeName;
                    roiNodeChanged = true;
                end

                pipeObj.nodes(iNode) = node;
            end

            if ~isfield(pipeObj.runProfiles, 'roiWorkflow') || ~isstruct(pipeObj.runProfiles.roiWorkflow)
                pipeObj.runProfiles.roiWorkflow = struct();
            end
            currentMode = '';
            if isfield(pipeObj.runProfiles.roiWorkflow, 'mode') && ~isempty(pipeObj.runProfiles.roiWorkflow.mode)
                currentMode = char(string(pipeObj.runProfiles.roiWorkflow.mode));
            end
            if ~strcmp(currentMode, modeName)
                pipeObj.runProfiles.roiWorkflow.mode = modeName;
                changed = true;
            end

            if roiNodeFound && roiNodeChanged
                changed = true;
            end

            if changed
                try
                    pipelineSave(pipeObj);
                    app.publishPipelineObjectToWorkspace(pipeObj);
                catch ME
                    warning('detecdiv:PipelineSave', '%s', ME.message);
                end
            end
        end

        function nodeType = getProjectCurrentRoiProducerType(app, shallowObj)
            nodeType = '';
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            [found, pipeObj] = app.getProjectDefaultPipelineObject(shallowObj);
            if ~found || isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end

            roiTypes = {'roiidentify','roipattern','roimanual','roigrid','roitracked'};
            fallbackType = '';
            for iNode = 1:numel(pipeObj.nodes)
                node = pipeObj.nodes(iNode);
                if ~isfield(node, 'type')
                    continue;
                end
                t = lower(char(string(node.type)));
                if any(strcmp(t, roiTypes))
                    enabled = true;
                    if isfield(node, 'enabled') && ~isempty(node.enabled)
                        enabled = logical(node.enabled);
                    end
                    if enabled
                        nodeType = t;
                        return;
                    end
                    if isempty(fallbackType)
                        fallbackType = t;
                    end
                end
            end
            nodeType = fallbackType;
        end

        function outType = canonicalRoiProducerType(app, nodeType) %#ok<INUSD>
            outType = lower(char(string(nodeType)));
            switch outType
                case {'roiidentify','roipattern'}
                    outType = 'roipattern';
            end
        end

        function changed = applyProjectRoiProducerChoice(app, shallowObj, nodeType, params)
            changed = false;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            if strcmpi(char(string(nodeType)), 'roiTracked')
                changed = app.ensureProjectTrackedPipelineNode(shallowObj, params);
                return;
            end

            currentType = app.getProjectCurrentRoiProducerType(shallowObj);
            targetCanon = app.canonicalRoiProducerType(nodeType);
            currentCanon = app.canonicalRoiProducerType(currentType);

            if ~isempty(currentType) && strcmp(currentCanon, targetCanon) && ~strcmpi(currentType, 'roiidentify')
                changed = app.updateProjectDefaultPipelineNodeParams(shallowObj, currentType, params);
            else
                changed = app.replaceProjectDefaultRoiProducerNode(shallowObj, nodeType, params);
            end
        end

        function params = getProjectDefaultPipelineNodeParams(app, shallowObj, nodeType)
            params = struct();
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            [found, pipeObj] = app.getProjectDefaultPipelineObject(shallowObj);
            if ~found || isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end

            targetType = lower(char(string(nodeType)));
            switch targetType
                case {'roipattern','roiidentify'}
                    matchTypes = {'roipattern','roiidentify'};
                case 'roimanual'
                    matchTypes = {'roimanual'};
                case 'roigrid'
                    matchTypes = {'roigrid'};
                otherwise
                    matchTypes = {targetType};
            end

            for iNode = 1:numel(pipeObj.nodes)
                node = pipeObj.nodes(iNode);
                if ~isfield(node, 'type')
                    continue;
                end
                if ~any(strcmpi(char(string(node.type)), matchTypes))
                    continue;
                end
                if isfield(node, 'params') && isstruct(node.params)
                    params = node.params;
                end
                return;
            end
        end

        function changed = setProjectDefaultPipelineRef(app, shallowObj, pipeObj) %#ok<INUSD>
            changed = false;
            if isempty(shallowObj) || ~isa(shallowObj,'shallow') || isempty(pipeObj) || ~isa(pipeObj,'pipeline')
                return;
            end

            if ~isprop(shallowObj,'runProfiles') || isempty(shallowObj.runProfiles) || ~isstruct(shallowObj.runProfiles)
                shallowObj.runProfiles = struct();
            end
            if ~isfield(shallowObj.runProfiles,'pipeline') || isempty(shallowObj.runProfiles.pipeline) || ~isstruct(shallowObj.runProfiles.pipeline)
                shallowObj.runProfiles.pipeline = struct();
            end

            pipeInfo = shallowObj.runProfiles.pipeline;
            jsonPath = fullfile(pipeObj.path, 'pipeline.json');
            pipeId = char(string(pipeObj.strid));

            curPath = '';
            curId = '';
            if isfield(pipeInfo,'defaultTemplatePath') && ~isempty(pipeInfo.defaultTemplatePath)
                curPath = char(string(pipeInfo.defaultTemplatePath));
            end
            if isfield(pipeInfo,'defaultTemplateId') && ~isempty(pipeInfo.defaultTemplateId)
                curId = char(string(pipeInfo.defaultTemplateId));
            end

            if ~strcmp(app.normalizePipelineRootPath(curPath), app.normalizePipelineRootPath(jsonPath))
                pipeInfo.defaultTemplatePath = jsonPath;
                changed = true;
            end
            if ~strcmp(curId, pipeId)
                pipeInfo.defaultTemplateId = pipeId;
                changed = true;
            end

            shallowObj.runProfiles.pipeline = pipeInfo;
        end

        function paths = resolveProjectPipelineJsonCandidates(app, shallowObj) %#ok<INUSD>
            paths = {};
            if isempty(shallowObj) || ~isa(shallowObj,'shallow')
                return;
            end
            if isempty(shallowObj.io.path) || isempty(shallowObj.io.file)
                return;
            end

            projectRoot = fullfile(char(string(shallowObj.io.path)), char(string(shallowObj.io.file)));
            if ~exist(projectRoot,'dir')
                return;
            end

            d = dir(fullfile(projectRoot,'*','pipeline.json'));
            if isempty(d) && exist(fullfile(projectRoot,'pipeline.json'),'file')
                d = dir(fullfile(projectRoot,'pipeline.json'));
            end
            if isempty(d)
                return;
            end

            seen = containers.Map('KeyType','char','ValueType','logical');
            for i = 1:numel(d)
                p = fullfile(d(i).folder, d(i).name);
                key = app.normalizeFsPath(p);
                if isempty(key) || isKey(seen, key)
                    continue;
                end
                seen(key) = true;
                paths{end+1} = p; %#ok<AGROW>
            end
        end

        function preferredPath = resolveProjectPreferredPipelineJson(app, shallowObj, configuredPath) %#ok<INUSD>
            preferredPath = '';
            if nargin < 3 || isempty(configuredPath)
                configuredPath = '';
            else
                configuredPath = char(string(configuredPath));
            end

            candidates = app.resolveProjectPipelineJsonCandidates(shallowObj);

            if ~isempty(configuredPath)
                cfgKey = app.normalizeFsPath(configuredPath);
                for i = 1:numel(candidates)
                    candPath = char(string(candidates{i}));
                    if strcmp(app.normalizeFsPath(candPath), cfgKey)
                        preferredPath = candPath;
                        return;
                    end
                end

                cfgFolder = '';
                try
                    [cfgParent,~,~] = fileparts(configuredPath);
                    [~,cfgFolder] = fileparts(cfgParent);
                    cfgFolder = char(string(cfgFolder));
                catch
                    cfgFolder = '';
                end

                if ~isempty(cfgFolder)
                    for i = 1:numel(candidates)
                        candPath = char(string(candidates{i}));
                        [candParent,~,~] = fileparts(candPath);
                        [~,candFolder] = fileparts(candParent);
                        if strcmpi(char(string(candFolder)), cfgFolder)
                            preferredPath = candPath;
                            return;
                        end
                    end
                end
            end

            if ~isempty(configuredPath) && exist(configuredPath,'file')
                preferredPath = configuredPath;
                return;
            end

            if numel(candidates) == 1
                preferredPath = char(string(candidates{1}));
            end
        end


   function registerRecentProject(app, projectPath)


    % --- Normaliser input -> string scalar
    projectPath = string(projectPath);
    projectPath = projectPath(1);
    projectPath = string(app.resolveRecentProjectPath(projectPath));
    if strlength(projectPath) == 0
        return;
    end

    % --- Purge + normaliser liste existante -> string colonne
    app.cleanRecentProjectsList(false);

    old = app.RecentProjects;
    if isempty(old)
        old = string.empty(0,1);
    else
        if iscell(old), old = string(old); end
        old = string(old);
        old = old(:);
    end

    % --- Prepend + dédoublonner (garde le plus récent)
    newList = [projectPath; old];
    newList = unique(newList, 'stable');

    % --- Limiter
    maxN = 10;
    if numel(newList) > maxN
        newList = newList(1:maxN);
    end

    app.RecentProjects = newList;

    app.saveRecentProjectsList();

    % --- Refresh menu
    app.refreshRecentProjectsMenu();
end


function registerRecentClassifier(app, classiMatPath)
    % classiMatPath = chemin absolu vers le *_classification.mat

    % --- Normaliser input -> string scalar
    classiMatPath = string(classiMatPath);
    classiMatPath = classiMatPath(1);

    % --- Purge + normaliser liste existante -> string colonne
    app.cleanRecentClassifiersList(false);

    old = app.RecentClassifiers;
    if isempty(old)
        old = string.empty(0,1);
    else
        if iscell(old), old = string(old); end
        old = string(old);
        old = old(:);
    end

    % --- Prepend + dédoublonner (garde le plus récent)
    newList = [classiMatPath; old];
    newList = unique(newList, 'stable');

    % --- Limiter
    maxN = 10;
    if numel(newList) > maxN
        newList = newList(1:maxN);
    end

    app.RecentClassifiers = newList;

    % --- Sauver sur disque
    try
        RecentClassifiers = app.RecentClassifiers; %#ok<NASGU>
        save(app.RecentClassifiersFile, 'RecentClassifiers');
    catch ME
        warning('Could not save recent classifiers list: %s', ME.message);
    end

    % --- Refresh menu
    app.refreshRecentClassifiersMenu();
end


function registerRecentPipeline(app, pipelineJsonPath)
    pipelineJsonPath = string(pipelineJsonPath);
    pipelineJsonPath = pipelineJsonPath(1);

    app.cleanRecentPipelinesList(false);

    old = app.RecentPipelines;
    if isempty(old)
        old = string.empty(0,1);
    else
        if iscell(old), old = string(old); end
        old = string(old);
        old = old(:);
    end

    newList = [pipelineJsonPath; old];
    newList = unique(newList, 'stable');

    maxN = 10;
    if numel(newList) > maxN
        newList = newList(1:maxN);
    end

    app.RecentPipelines = newList;

    try
        RecentPipelines = app.RecentPipelines; %#ok<NASGU>
        save(app.RecentPipelinesFile, 'RecentPipelines');
    catch ME
        warning('Could not save recent pipelines list: %s', ME.message);
    end

    app.refreshRecentPipelinesMenu();
end


function refreshRecentPipelinesMenu(app)
    if ~app.componentExists('OpenrecentPipelineMenu')
        return;
    end

    if ~isempty(app.OpenrecentPipelineMenu.Children)
        delete(app.OpenrecentPipelineMenu.Children);
    end

    if isempty(app.RecentPipelines)
        uimenu(app.OpenrecentPipelineMenu, ...
            'Text','(No recent pipelines)', ...
            'Enable','off');
        return;
    end

    for k = 1:numel(app.RecentPipelines)
        thisPath = app.RecentPipelines(k);

        [parentDir, shortName] = fileparts(thisPath);
        [~, pipeFolder] = fileparts(parentDir);
        if strlength(pipeFolder) == 0
            labelName = shortName;
        else
            labelName = pipeFolder;
        end

        label = sprintf('%d. %s', k, labelName);

        uimenu(app.OpenrecentPipelineMenu, ...
            'Text', label, ...
            'Tooltip', char(thisPath), ...
            'MenuSelectedFcn', @(src,evt)app.openRecentPipelineCallback(thisPath));
    end

    uimenu(app.OpenrecentPipelineMenu, ...
        'Separator','on', ...
        'Text','Clear recent list', ...
        'MenuSelectedFcn', @(src,evt)app.clearRecentPipelines());
end


function openRecentPipelineCallback(app, pipelineJsonPath)

    if isstring(pipelineJsonPath)
        pipelineJsonPath = char(pipelineJsonPath(1));
    end

    if ~isfile(pipelineJsonPath)
        uialert(app.DetecDivUIFigure, ...
            sprintf('Pipeline not found:\n%s\nIt will be removed from recent list.', pipelineJsonPath), ...
            'Missing pipeline', ...
            'Icon','warning');

        app.cleanRecentPipelinesList(true);
        app.refreshRecentPipelinesMenu();
        return;
    end

    d = uiprogressdlg(app.DetecDivUIFigure, ...
        'Title','Please Wait...', ...
        'Message','Loading selected pipeline...');
    d.Value = 0.33;

    [pipeObj, msg] = pipelineLoad(pipelineJsonPath);

    if isempty(pipeObj)
        close(d);
        if ~isempty(msg)
            uialert(app.DetecDivUIFigure, msg, 'Error', 'Icon', 'error');
        end
        return;
    end

    d.Value = 0.66;
    pause(0.2);

    varName = app.nextPipelineVarName(pipeObj);
    assignin('base', varName, pipeObj);

    app.registerRecentPipeline(string(pipelineJsonPath));

    gatherVarsFromWorkspace(app);
    displayNodes(app);
    try
        app.selectPipelineNodeByJsonPath(pipelineJsonPath);
    catch
    end

    d.Message = 'Opening pipeline editor...';
    drawnow limitrate nocallbacks;
    app.openPipelineWithContext(pipeObj, d);
end


function refreshRecentClassifiersMenu(app)

    % Vider l'ancien contenu
    if ~isempty(app.OpenrecentClassiMenu.Children)
        delete(app.OpenrecentClassiMenu.Children);
    end

    if isempty(app.RecentClassifiers)
        uimenu(app.OpenrecentClassiMenu, ...
            'Text','(No recent classifiers)', ...
            'Enable','off');
        return;
    end

    for k = 1:numel(app.RecentClassifiers)
        thisClassiPath = app.RecentClassifiers(k);

        [~, shortName, ext] = fileparts(thisClassiPath);
        shortName = shortName + ext;  % e.g. "yolo_1_classification.mat"

        label = sprintf('%d. %s', k, shortName);

        uimenu(app.OpenrecentClassiMenu, ...
            'Text', label, ...
            'Tooltip', char(thisClassiPath), ...
            'MenuSelectedFcn', @(src,evt)app.openRecentClassifierCallback(thisClassiPath));
    end

    uimenu(app.OpenrecentClassiMenu, ...
        'Separator','on', ...
        'Text','Clear recent list', ...
        'MenuSelectedFcn', @(src,evt)app.clearRecentClassifiers());
end


        function refreshRecentProjectsMenu(app)

            if ~isempty(app.OpenrecentMenu.Children)
                delete(app.OpenrecentMenu.Children);
            end

            if isempty(app.RecentProjects)
                uimenu(app.OpenrecentMenu, ...
                    'Text','(No recent projects)', ...
                    'Enable','off');
                return;
            end

            for k = 1:numel(app.RecentProjects)
                thisPath = app.RecentProjects(k);
                
                % complet avec .mat

                [~, shortName, ext] = fileparts(thisPath);
                shortName = shortName + ext;  % ex: tmpProject.mat

                label = sprintf('%d. %s', k, shortName);

                uimenu(app.OpenrecentMenu, ...
                    'Text', label, ...
                    'Tooltip', char(thisPath), ...
                    'MenuSelectedFcn', @(src,evt)app.openRecentProjectCallback(thisPath));
            end

            uimenu(app.OpenrecentMenu, 'Separator','on', ...
                'Text','Clear recent list', ...
                'MenuSelectedFcn', @(src,evt)app.clearRecentProjects());
        end


function openRecentProjectCallback(app, projectPath)

    % force scalaire string -> char pour être sûr
    if isstring(projectPath)
        projectPath = projectPath(1); % prend juste le premier élément si jamais c'est un array
        projectPathChar = char(projectPath);
    else
        projectPathChar = projectPath; % déjà char
    end

    originalProjectPathChar = projectPathChar;
    projectPathChar = app.resolveRecentProjectPath(projectPathChar);

    if ~isfile(projectPathChar)
        uialert(app.DetecDivUIFigure, ...
            sprintf('Project not found:\n%s\nIt will be removed from recent list.', originalProjectPathChar), ...
            'Missing project', ...
            'Icon','warning');

        app.cleanRecentProjectsList(true);
        app.saveRecentProjectsList();
        app.refreshRecentProjectsMenu();
        return;
    end

    d = uiprogressdlg(app.DetecDivUIFigure, ...
        'Title','Please Wait...', ...
        'Message','Loading selected project...');
    d.Value = 0.33;

    [proj, msg] = shallowLoad(projectPathChar);

    if isempty(proj)
        close(d);
        if ~isempty(msg)
            uialert(app.DetecDivUIFigure, msg, 'Error', 'Icon', 'error');
        end
        return;
    end

    d.Message = 'Checking Hub access and acquiring a local edit lease...';
    drawnow;
    [proj, hubAccess] = detecdiv_hub_prepare_project_open(proj);

    d.Value = 0.66;
    pause(0.2);

    name = makeSafeVariableName(proj.io.file);
    assignin('base', name, proj);


    % on réenregistre le chemin propre (pas le tableau chelou)
    app.registerRecentProject(string(projectPathChar));

    gatherVarsFromWorkspace(app);
    displayNodes(app);

    if hubAccess.hubManaged && hubAccess.readOnly
        uialert(app.DetecDivUIFigure, hubAccess.reason, 'Hub project opened read-only', 'Icon', 'warning');
    end

    close(d);
end


function openRecentClassifierCallback(app, classiMatPath)

    % sécuriser en char scalaire
    if isstring(classiMatPath)
        classiMatPath = char(classiMatPath(1));
    end

    if ~isfile(classiMatPath)
        uialert(app.DetecDivUIFigure, ...
            sprintf('Classifier not found:\n%s\nIt will be removed from recent list.', classiMatPath), ...
            'Missing classifier', ...
            'Icon','warning');

        app.cleanRecentClassifiersList(true);
        app.refreshRecentClassifiersMenu();
        return;
    end

    d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
        'Message','Loading selected classifier...');
    d.Value = 0.33;

    % recharge via classiLoad(fullpath)
    [classiObj, msg] = classiLoad(classiMatPath);

    if isempty(classiObj)
        close(d);
        if ~isempty(msg)
            uialert(app.DetecDivUIFigure,msg,'Error','Icon','error');
        end
        return;
    end

    d.Value = 0.66;
    pause(0.2);

    % publier dans base workspace, cohérent avec ta callback existante
    name = [classiObj.strid '_indep'];
    assignin('base', name, classiObj);

    % le remettre en tête de la liste "récents"
    app.registerRecentClassifier(classiMatPath);

    % refresh l'état interne / arbres etc.
    gatherVarsFromWorkspace(app);
    displayNodes(app);

    close(d);
end

function cleanRecentClassifiersList(app, validateExisting)
    if nargin < 2 || isempty(validateExisting)
        validateExisting = true;
    end
    rc = string(app.RecentClassifiers(:));
    rc = rc(rc ~= "");  % vire les vides

    keep = true(size(rc));
    if validateExisting
        keep = false(size(rc));
        for k = 1:numel(rc)
            keep(k) = isfile(rc(k));  % on ne garde que les chemins encore valides
        end
    end
    rc = rc(keep);

    % unicité (conserve l'ordre LRU)
    [~, ia] = unique(rc, 'stable');
    rc = rc(sort(ia,'ascend'));

    % max 10
    if numel(rc) > 10
        rc = rc(1:10);
    end

    app.RecentClassifiers = rc;
end


function cleanRecentPipelinesList(app, validateExisting)
    if nargin < 2 || isempty(validateExisting)
        validateExisting = true;
    end
    rp = string(app.RecentPipelines(:));
    rp = rp(rp ~= "");

    keep = true(size(rp));
    if validateExisting
        keep = false(size(rp));
        for k = 1:numel(rp)
            keep(k) = isfile(rp(k));
        end
    end
    rp = rp(keep);

    [~, ia] = unique(rp, 'stable');
    rp = rp(ia);

    if numel(rp) > 10
        rp = rp(1:10);
    end

    app.RecentPipelines = rp;
end


         function cleanRecentProjectsList(app, validateExisting)
        if nargin < 2 || isempty(validateExisting)
            validateExisting = true;
        end
        rp = string(app.RecentProjects(:));
        rp = rp(rp ~= "");

        if ~validateExisting
            [~, ia] = unique(rp, 'stable');
            rp = rp(ia);
            if numel(rp) > 10
                rp = rp(1:10);
            end
            app.RecentProjects = rp;
            return;
        end

        out = strings(0,1);
        for k = 1:numel(rp)
            resolved = string(app.resolveRecentProjectPath(rp(k)));
            if strlength(resolved) > 0
                out(end+1,1) = resolved; %#ok<AGROW>
            elseif ~validateExisting
                out(end+1,1) = rp(k); %#ok<AGROW>
            end

        end
        rp = out;

        % unicité en préservant l'ordre
     [~, ia] = unique(rp, 'stable');
rp = rp(ia);


        % max 10
        if numel(rp) > 10
            rp = rp(1:10);
        end

        app.RecentProjects = rp;
         end

         function projectPath = resolveRecentProjectPath(app, projectPath) %#ok<INUSD>
             projectPath = char(string(projectPath));
             projectPath = strtrim(projectPath);
             if isempty(projectPath)
                 return;
             end

             [pth, nam, ext] = fileparts(projectPath);
             if strcmpi(ext, '.mat')
                 jsonCandidate = fullfile(pth, [nam '.json']);
                 if isfile(jsonCandidate)
                     projectPath = jsonCandidate;
                     return;
                 end
             elseif isempty(ext)
                 jsonCandidate = fullfile(pth, [nam '.json']);
                 if isfile(jsonCandidate)
                     projectPath = jsonCandidate;
                     return;
                 end
                 matCandidate = fullfile(pth, [nam '.mat']);
                 if isfile(matCandidate)
                     projectPath = matCandidate;
                     return;
                 end
             end

             if isfolder(projectPath)
                 [parentDir, folderName] = fileparts(projectPath);
                 jsonCandidate = fullfile(parentDir, [folderName '.json']);
                 if isfile(jsonCandidate)
                     projectPath = jsonCandidate;
                     return;
                 end
                 matCandidate = fullfile(parentDir, [folderName '.mat']);
                 if isfile(matCandidate)
                     projectPath = matCandidate;
                     return;
                 end
             end

             if ~isfile(projectPath)
                 projectPath = '';
             end
         end

         function saveRecentProjectsList(app)
             try
                 RecentProjects = app.RecentProjects; %#ok<NASGU>
                 save(app.RecentProjectsFile, 'RecentProjects');
             catch ME
                 warning('Could not save recent projects list: %s', ME.message);
             end
         end

   function clearRecentClassifiers(app)
    choice = uiconfirm(app.DetecDivUIFigure, ...
        'Clear the list of recent classifiers ?', ...
        'Confirm', ...
        'Options', {'Yes','No'}, ...
        'DefaultOption','No', ...
        'CancelOption','No');

    if strcmp(choice,'Yes')
        app.RecentClassifiers = strings(0);

        try
            RecentClassifiers = app.RecentClassifiers; %#ok<NASGU>
            save(app.RecentClassifiersFile, 'RecentClassifiers');
        catch ME
            warning('Could not save cleared recent classifiers list: %s', ME.message);
        end

        app.refreshRecentClassifiersMenu();
    end
end


   function clearRecentPipelines(app)
    choice = uiconfirm(app.DetecDivUIFigure, ...
        'Clear the list of recent pipelines ?', ...
        'Confirm', ...
        'Options', {'Yes','No'}, ...
        'DefaultOption','No', ...
        'CancelOption','No');

    if strcmp(choice,'Yes')
        app.RecentPipelines = strings(0);

        try
            RecentPipelines = app.RecentPipelines; %#ok<NASGU>
            save(app.RecentPipelinesFile, 'RecentPipelines');
        catch ME
            warning('Could not save cleared recent pipelines list: %s', ME.message);
        end

        app.refreshRecentPipelinesMenu();
        try
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        catch
        end
    end
end


        function clearRecentProjects(app)
            choice = uiconfirm(app.DetecDivUIFigure, ...
                'Clear the list of recent projects ?', ...
                'Confirm', ...
                'Options', {'Yes','No'}, ...
                'DefaultOption','No', ...
                'CancelOption','No');

            if strcmp(choice,'Yes')
                app.RecentProjects = strings(0);

                % sauver la liste vide
                try
                    RecentProjects = app.RecentProjects; %#ok<NASGU>
                    save(app.RecentProjectsFile, 'RecentProjects');
                catch ME
                    warning('Could not save cleared recent list: %s', ME.message);
                end

                app.refreshRecentProjectsMenu();
            end
        end


       function initRecentProjectsSystem(app)
    % D?termine le dossier repo (un cran au-dessus de GUI/)
   % thisAppFile = mfilename('fullpath');  % .../GUI/detecdiv
   % [guiFolder, ~, ~] = fileparts(thisAppFile);
    repoFolder = prefdir; %fileparts(guiFolder);    % remonte d'un cran

    % Fichiers de persistance
    app.RecentProjectsFile     = fullfile(repoFolder, 'recentProjects.mat');
    app.RecentClassifiersFile  = fullfile(repoFolder, 'recentClassifiers.mat');
    app.RecentPipelinesFile    = fullfile(repoFolder, 'recentPipelines.mat');

    % Charger projets
    if exist(app.RecentProjectsFile, 'file')
        S = load(app.RecentProjectsFile, 'RecentProjects');
        if isfield(S,'RecentProjects') && ~isempty(S.RecentProjects)
            app.RecentProjects = string(S.RecentProjects(:));
        end
    end

    % Charger classifieurs
    if exist(app.RecentClassifiersFile, 'file')
        S2 = load(app.RecentClassifiersFile, 'RecentClassifiers');
        if isfield(S2,'RecentClassifiers') && ~isempty(S2.RecentClassifiers)
            app.RecentClassifiers = string(S2.RecentClassifiers(:));
        end
    end

    % Charger pipelines
    if exist(app.RecentPipelinesFile, 'file')
        S3 = load(app.RecentPipelinesFile, 'RecentPipelines');
        if isfield(S3,'RecentPipelines') && ~isempty(S3.RecentPipelines)
            app.RecentPipelines = string(S3.RecentPipelines(:));
        end
    end

    % Nettoyer / rafra?chir menus
    app.cleanRecentProjectsList(false);
    app.saveRecentProjectsList();
    app.refreshRecentProjectsMenu();

    app.cleanRecentClassifiersList(false);
    app.refreshRecentClassifiersMenu();

    app.cleanRecentPipelinesList(false);
    app.refreshRecentPipelinesMenu();
        function [ok,node,pipeObj] = getPipelineNodeByIndex(app, pipeIdx, modIdx)
            ok = false;
            node = struct();
            pipeObj = [];

            if pipeIdx > numel(app.Data.Pipeline)
                return;
            end

            pipeVar = app.Data.Pipeline{pipeIdx};
            try
                pipeObj = evalin('base', pipeVar);
            catch
                return;
            end

            if ~isa(pipeObj,'pipeline') || ~isprop(pipeObj,'nodes') || modIdx > numel(pipeObj.nodes)
                pipeObj = [];
                return;
            end

            node = pipeObj.nodes(modIdx);
            ok = true;
        end

        function [nType, modObj] = buildPipelineModuleObject(app, node) %#ok<INUSD>
            nType = '';
            modObj = [];

            if ~(isstruct(node) && isfield(node,'type') && ~isempty(node.type))
                return;
            end

            nType = lower(char(string(node.type)));

            if strcmp(nType,'processor')
                tmpProc = process(tempdir, 'pipeline_module', randi(1e9));

                pkgName = '';
                if isfield(node,'pkg') && ~isempty(node.pkg)
                    pkgName = char(string(node.pkg));
                end

                if ~isempty(pkgName)
                    tmpProc.processFun = [pkgName '.process'];
                    try
                        p0 = feval([pkgName '.setparam'], struct());
                    catch
                        p0 = struct();
                    end
                    if isstruct(p0)
                        tmpProc.processArg = p0;
                    end
                elseif isfield(node,'func') && ~isempty(node.func)
                    tmpProc.processFun = char(string(node.func));
                end

                if isfield(node,'params') && isstruct(node.params)
                    if isempty(tmpProc.processArg) || ~isstruct(tmpProc.processArg)
                        tmpProc.processArg = node.params;
                    else
                        fn = fieldnames(node.params);
                        for fi = 1:numel(fn)
                            tmpProc.processArg.(fn{fi}) = node.params.(fn{fi});
                        end
                    end
                end

                if isfield(node,'id') && ~isempty(node.id)
                    tmpProc.strid = char(string(node.id));
                end

                modObj = tmpProc;
                return;
            end

            if strcmp(nType,'classifier')
                tmpClassi = classi(tempdir, 'pipeline_module', randi(1e9));

                if isfield(node,'id') && ~isempty(node.id)
                    tmpClassi.strid = char(string(node.id));
                end

                pkgName = '';
                if isfield(node,'pkg') && ~isempty(node.pkg)
                    pkgName = char(string(node.pkg));
                end

                if ~isempty(pkgName)
                    tmpClassi.classifierPkg = pkgName;
                    if isempty(tmpClassi.classifyFun)
                        tmpClassi.classifyFun = [pkgName '.classify'];
                    end
                    if isempty(tmpClassi.trainingFun)
                        tmpClassi.trainingFun = [pkgName '.train'];
                    end

                    tmpClassi.category = {'Image'};
                    try
                        spec = feval([pkgName '.executionSpec'], tmpClassi);
                        if isstruct(spec) && isfield(spec,'category') && ~isempty(spec.category)
                            tmpClassi.category = classiNormalizeCategory(spec.category);
                        elseif strcmpi(pkgName,'cnn_lstm')
                            tmpClassi.category = {'LSTM'};
                        end
                    catch
                        if strcmpi(pkgName,'cnn_lstm')
                            tmpClassi.category = {'LSTM'};
                        end
                    end
                else
                    tmpClassi.category = {'Image'};
                end

                if isfield(node,'func') && ~isempty(node.func)
                    tmpClassi.classifyFun = char(string(node.func));
                end

                if isfield(node,'params') && isstruct(node.params)
                    if isfield(node.params,'classes') && ~isempty(node.params.classes)
                        cls = node.params.classes;
                        if isstring(cls), cls = cellstr(cls); end
                        if ischar(cls), cls = {cls}; end
                        tmpClassi.classes = cls;
                    end
                end

                tmpClassi.category = classiNormalizeCategory(tmpClassi.category);
                modObj = tmpClassi;
                return;
            end
        end

        function openPipelineModuleByIndex(app, pipeIdx, modIdx)
            [ok,node,pipeObj] = app.getPipelineNodeByIndex(pipeIdx, modIdx);
            if ~ok
                return;
            end

            [nType, modObj] = app.buildPipelineModuleObject(node);
            switch nType
                case 'processor'
                    processDataGUI([], modObj);
                case 'classifier'
                    classifierGUI(modObj);
                otherwise
                    app.openPipelineWithContext(pipeObj);
            end
        end

end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.applyMainWindowLayout();
            app.resetMainPanelState();
            checkInstalledToolboxes;
            initUserPreferences;

            initRecentProjectsSystem(app);
            app.WorkspaceEventListenerId = detecdiv_event('subscribe', ...
                'workspaceChanged', @(payload, eventName) app.onExternalWorkspaceChanged(payload, eventName));


            gatherVarsFromWorkspace(app)
            displayNodes(app)
        end

        function tf = componentExists(app, name)
            tf = false;
            try
                if ~isprop(app, name)
                    return;
                end
                value = app.(name);
                if isempty(value)
                    return;
                end
                try
                    tf = isvalid(value);
                catch
                    tf = true;
                end
            catch
                tf = false;
            end
        end

        function deleteNodeChildrenIfPresent(app, name)
            if ~app.componentExists(name)
                return;
            end
            try
                t = app.(name).Children;
                delete(t);
            catch
            end
        end

        function setComponentPosition(app, name, position)
            if ~app.componentExists(name)
                return;
            end
            try
                app.(name).Position = position;
            catch
            end
        end

        function setComponentVisible(app, name, value)
            if ~app.componentExists(name)
                return;
            end
            try
                app.(name).Visible = value;
            catch
            end
        end

        function setComponentText(app, name, value)
            if ~app.componentExists(name)
                return;
            end
            try
                app.(name).Text = value;
            catch
            end
        end

        function clearAxesIfPresent(app, name)
            if ~app.componentExists(name)
                return;
            end
            try
                cla(app.(name));
            catch
            end
        end

        function onExternalWorkspaceChanged(app, payload, eventName) %#ok<INUSD>
            if isempty(app) || ~isvalid(app) || isempty(app.DetecDivUIFigure) || ~isvalid(app.DetecDivUIFigure)
                return;
            end
            app.syncProjectFromWorkspaceEvent(payload);
            try
                gatherVarsFromWorkspace(app);
                displayNodes(app);
            catch
            end
            RefreshtreewindowMenuSelected(app, []);
            drawnow limitrate;
        end

        function syncProjectFromWorkspaceEvent(app, payload) %#ok<INUSD>
            if nargin < 2 || ~isstruct(payload)
                return;
            end

            projectObj = [];
            if isfield(payload, 'projectObj') && isa(payload.projectObj, 'shallow')
                projectObj = payload.projectObj;
            elseif isfield(payload, 'shallowObj') && isa(payload.shallowObj, 'shallow')
                projectObj = payload.shallowObj;
            elseif isfield(payload, 'shallow') && isa(payload.shallow, 'shallow')
                projectObj = payload.shallow;
            end

            projectMatPath = '';
            if isfield(payload, 'projectMatPath') && ~isempty(payload.projectMatPath)
                projectMatPath = char(string(payload.projectMatPath));
            elseif isfield(payload, 'projectPath') && ~isempty(payload.projectPath)
                projectMatPath = app.projectMatPathFromEventPath(payload.projectPath);
            end

            if isempty(projectObj) && ~isempty(projectMatPath) && exist(projectMatPath, 'file') == 2
                try
                    projectObj = app.loadProjectMatRaw(projectMatPath);
                catch ME
                    warning('detecdiv:WorkspaceEventLoadFailed', ...
                        'Unable to load project from event path "%s": %s', projectMatPath, ME.message);
                end
            end

            if isempty(projectObj) || ~isa(projectObj, 'shallow')
                return;
            end

            varName = app.projectWorkspaceVarNameFromEvent(payload, projectObj);
            if isempty(varName)
                return;
            end

            try
                assignin('base', varName, projectObj);
            catch ME
                warning('detecdiv:WorkspaceEventAssignFailed', ...
                    'Unable to publish project "%s" to base workspace: %s', varName, ME.message);
            end
        end

        function [labels, runPaths] = listClassifierPipelineRuns(app, clas) %#ok<INUSD>
            labels = {};
            runPaths = {};
            if isempty(clas) || ~isa(clas,'classi')
                return;
            end
            classiPath = '';
            try
                classiPath = char(string(clas.path));
            catch
            end
            if isempty(classiPath)
                return;
            end
            runRoot = fullfile(classiPath, 'pipeline_runs');
            if exist(runRoot, 'dir') ~= 7
                return;
            end

            dd = dir(runRoot);
            if isempty(dd)
                return;
            end
            dd = dd([dd.isdir]);
            names = {dd.name};
            keep = ~ismember(names, {'.','..'});
            dd = dd(keep);
            if isempty(dd)
                return;
            end
            [~, order] = sort(lower({dd.name}));
            dd = dd(order);

            for ir = 1:numel(dd)
                candidate = fullfile(runRoot, dd(ir).name);
                runJson = fullfile(candidate, 'run.json');
                if exist(runJson, 'file') ~= 2
                    continue;
                end
                label = dd(ir).name;
                try
                    [runObj, msg] = pipelineRunLoad(runJson);
                    if ~isempty(runObj)
                        runName = app.formatPipelineRunLabel(runObj, ir);
                        runStatus = '';
                        try
                            if isprop(runObj,'status') && ~isempty(runObj.status)
                                runStatus = char(string(runObj.status));
                            end
                        catch
                        end
                        if isempty(runStatus)
                            label = runName;
                        else
                            label = [runName ' (' runStatus ')'];
                        end
                    elseif ~isempty(msg)
                        label = [label ' (unreadable)'];
                    end
                catch
                    label = [label ' (unreadable)'];
                end
                labels{end+1} = label; %#ok<AGROW>
                runPaths{end+1} = candidate; %#ok<AGROW>
            end
        end

        function matPath = projectMatPathFromEventPath(app, projectPath) %#ok<INUSD>
            matPath = '';
            if isempty(projectPath)
                return;
            end
            p = char(string(projectPath));
            if exist(p, 'file') == 2
                matPath = p;
                return;
            end
            if exist([p '.mat'], 'file') == 2
                matPath = [p '.mat'];
                return;
            end
            if exist(p, 'dir') == 7
                [parentDir, projectName] = fileparts(p);
                candidate = fullfile(parentDir, [projectName '.mat']);
                if exist(candidate, 'file') == 2
                    matPath = candidate;
                end
            end
        end

        function varName = projectWorkspaceVarNameFromEvent(app, payload, projectObj) %#ok<INUSD>
            varName = '';
            if isfield(payload, 'projectVarName') && ~isempty(payload.projectVarName)
                varName = char(string(payload.projectVarName));
            end
            if isempty(varName) && isfield(payload, 'workspaceVar') && ~isempty(payload.workspaceVar)
                varName = char(string(payload.workspaceVar));
            end
            if isempty(varName)
                varName = app.findExistingProjectWorkspaceVar(projectObj);
            end
            if isempty(varName)
                try
                    varName = char(string(projectObj.id));
                catch
                    varName = '';
                end
            end
            if isempty(varName)
                try
                    [~, file] = projectObj.getPath;
                    varName = char(string(file));
                catch
                    varName = '';
                end
            end
            varName = matlab.lang.makeValidName(varName);
            if isempty(varName)
                varName = 'shallowObj';
            end
        end

        function varName = findExistingProjectWorkspaceVar(app, projectObj) %#ok<INUSD>
            varName = '';
            if isempty(projectObj) || ~isa(projectObj, 'shallow')
                return;
            end
            targetPath = '';
            targetFile = '';
            try
                [targetPath, targetFile] = projectObj.getPath;
            catch
            end
            targetId = '';
            try
                targetId = char(string(projectObj.id));
            catch
            end

            try
                vars = evalin('base', 'who');
            catch
                vars = {};
            end
            for iVar = 1:numel(vars)
                try
                    candidate = evalin('base', vars{iVar});
                catch
                    continue;
                end
                if ~isa(candidate, 'shallow')
                    continue;
                end
                try
                    [candidatePath, candidateFile] = candidate.getPath;
                    if ~isempty(targetPath) && strcmp(candidatePath, targetPath) && strcmp(candidateFile, targetFile)
                        varName = vars{iVar};
                        return;
                    end
                catch
                end
                try
                    if ~isempty(targetId) && strcmp(char(string(candidate.id)), targetId)
                        varName = vars{iVar};
                        return;
                    end
                catch
                end
            end
        end

        function applyMainWindowLayout(app)
            if ~isvalid(app.DetecDivUIFigure)
                return;
            end

            figPos = app.DetecDivUIFigure.Position;
            app.DetecDivUIFigure.Position = [figPos(1) figPos(2) max(figPos(3), 708) max(figPos(4), 646)];

            if ~isempty(app.MainGrid) && isvalid(app.MainGrid)
                app.MainGrid.Padding = [8 8 8 8];
                app.MainGrid.ColumnSpacing = 12;
                app.MainGrid.RowSpacing = 0;
                app.MainGrid.ColumnWidth = {241, '1x'};
                app.MainGrid.RowHeight = {'1x'};
            end

            app.setComponentPosition('UIAxes', [0 16 417 341]);
            app.setComponentPosition('ProjectInformationLabel', [8 361 410 240]);
            app.setComponentPosition('AdddataButton', [20 60 185 43]);
            app.setComponentPosition('UpdaterawdatapathButton', [232 60 185 43]);
            app.setComponentPosition('IdentifyROIsinpositionsButton', [20 211 385 46]);
            app.setComponentPosition('ExtractROIhypervolumesButton', [20 153 389 47]);
            app.setComponentPosition('AddclassifierButton', [20 12 185 43]);
            app.setComponentPosition('AddprocessorButton', [232 12 185 43]);
            app.setComponentPosition('ClassifydataButton', [20 12 185 43]);
            app.setComponentPosition('ProcessdataButton', [232 12 185 43]);
            app.setComponentPosition('OpenButton', [282 12 136 40]);
            app.setComponentPosition('InspectRunButton', [282 60 136 40]);
            app.setComponentPosition('EditRunButton', [282 108 136 40]);
        end

        function applyTextOnlyMainPanelLayout(app)
            app.setComponentPosition('ProjectInformationLabel', [8 112 420 489]);
            app.setComponentPosition('AdddataButton', [20 60 185 43]);
            app.setComponentPosition('UpdaterawdatapathButton', [232 60 185 43]);
            app.setComponentPosition('AddclassifierButton', [20 12 185 43]);
            app.setComponentPosition('AddprocessorButton', [232 12 185 43]);
            app.setComponentPosition('ClassifydataButton', [20 12 185 43]);
            app.setComponentPosition('ProcessdataButton', [232 12 185 43]);
            app.setComponentPosition('OpenButton', [282 12 136 40]);
            app.setComponentPosition('InspectRunButton', [282 60 136 40]);
            app.setComponentPosition('EditRunButton', [282 108 136 40]);
        end

        function applyImageMainPanelLayout(app)
            app.setComponentPosition('ProjectInformationLabel', [8 361 410 240]);
            app.setComponentPosition('OpenButton', [282 12 136 40]);
            app.setComponentPosition('InspectRunButton', [282 60 136 40]);
            app.setComponentPosition('EditRunButton', [282 108 136 40]);
        end

        function resetMainPanelState(app)
            try, app.ProjectsPanel.Title = 'Projects'; catch, end
            app.setComponentText('ProjectInformationLabel', 'Project Information');
            app.setComponentVisible('ProjectInformationLabel', 'on');

            app.setComponentVisible('AdddataButton', 'off');
            app.setComponentVisible('AddclassifierButton', 'off');
            app.setComponentVisible('UpdaterawdatapathButton', 'off');
            app.setComponentVisible('IdentifyROIsinpositionsButton', 'off');
            app.setComponentVisible('ExtractROIhypervolumesButton', 'off');
            app.setComponentVisible('ClassifydataButton', 'off');
            app.setComponentVisible('AddprocessorButton', 'off');
            app.setComponentVisible('ProcessdataButton', 'off');
            app.setComponentVisible('InspectRunButton', 'off');
            app.setComponentVisible('EditRunButton', 'off');
            app.setComponentVisible('OpenButton', 'off');

            app.clearAxesIfPresent('UIAxes');
            app.setComponentVisible('UIAxes', 'off');
            app.applyTextOnlyMainPanelLayout();
        end

        function txt = formatProjectHubStatusText(app, shallowObj)
            txt = 'Hub project status: not Hub-managed';
            try
                if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                    return;
                end

                hub = detecdiv_hub_local_project_state(shallowObj);
                projectId = app.hubTextField(hub, {'hub_project_id','project_id','id'});
                hubManaged = app.hubLogicalField(hub, {'hubManaged','hub_managed'}, ~isempty(projectId));
                if ~hubManaged && isempty(projectId)
                    return;
                end

                isReadOnly = app.hubLogicalField(hub, {'read_only','readOnly'}, false);
                mode = app.hubTextField(hub, {'mode'});
                if isReadOnly
                    state = 'locked/read-only';
                elseif strcmpi(mode, 'lease_active')
                    state = 'editable (local lease active)';
                elseif strcmpi(mode, 'write_granted')
                    state = 'editable';
                else
                    state = 'Hub-managed (no local edit lease)';
                end

                parts = cell(1, 12);
                nParts = 1;
                parts{nParts} = ['Hub project status: ' state];
                if ~isempty(projectId)
                    nParts = nParts + 1;
                    parts{nParts} = ['Hub project id: ' projectId];
                end
                checkedAt = app.hubTextField(hub, {'checked_at','loaded_from_hub_at','loaded_from_catalog_at'});
                if ~isempty(checkedAt)
                    nParts = nParts + 1;
                    parts{nParts} = ['Last Hub check: ' checkedAt];
                end
                reason = app.hubTextField(hub, {'reason'});
                if ~isempty(reason)
                    nParts = nParts + 1;
                    parts{nParts} = ['Hub note: ' reason];
                end
                locks = struct([]);
                if isfield(hub, 'active_locks') && ~isempty(hub.active_locks)
                    if isstruct(hub.active_locks)
                        locks = hub.active_locks(:);
                    elseif iscell(hub.active_locks)
                        try
                            locks = vertcat(hub.active_locks{:});
                        catch
                        end
                    end
                end
                if ~isempty(locks)
                    lock = locks(1);
                    lockKind = app.hubTextField(lock, {'kind','lock_kind','type'});
                    holder = app.hubTextField(lock, {'holder','holder_key','requested_by'});
                    host = app.hubTextField(lock, {'host','holder_host','requested_from_host'});
                    heartbeatAt = app.hubTextField(lock, {'heartbeat_at','heartbeatAt'});
                    expiresAt = app.hubTextField(lock, {'expires_at','expiresAt'});
                    if ~isempty(lockKind)
                        nParts = nParts + 1;
                        parts{nParts} = ['Hub lock type: ' lockKind];
                    end
                    if ~isempty(holder) || ~isempty(host)
                        nParts = nParts + 1;
                        ownerText = holder;
                        if ~isempty(host)
                            if isempty(ownerText)
                                ownerText = host;
                            else
                                ownerText = [ownerText ' @ ' host];
                            end
                        end
                        parts{nParts} = ['Hub lock owner: ' ownerText];
                    end
                    if ~isempty(heartbeatAt)
                        nParts = nParts + 1;
                        parts{nParts} = ['Hub lock heartbeat (UTC): ' heartbeatAt];
                    end
                    if ~isempty(expiresAt)
                        nParts = nParts + 1;
                        parts{nParts} = ['Hub lock expires (UTC): ' expiresAt];
                    end
                    if numel(locks) > 1
                        nParts = nParts + 1;
                        parts{nParts} = sprintf('Additional Hub locks: %d', numel(locks) - 1);
                    end
                    if isReadOnly
                        nParts = nParts + 1;
                        parts{nParts} = 'Hub action: right-click the project to inspect or release the lock.';
                    end
                end
                txt = strjoin(parts(1:nParts), newline);
            catch
                txt = 'Hub project status: unavailable';
            end
        end

        function txt = hubTextField(~, S, names)
            txt = '';
            if ~isstruct(S)
                return;
            end
            for k = 1:numel(names)
                name = names{k};
                try
                    if isfield(S, name) && ~isempty(S.(name))
                        txt = char(string(S.(name)));
                        return;
                    end
                catch
                end
            end
        end

        function value = hubLogicalField(~, S, names, defaultValue)
            value = defaultValue;
            if ~isstruct(S)
                return;
            end
            for k = 1:numel(names)
                name = names{k};
                try
                    if ~isfield(S, name) || isempty(S.(name))
                        continue;
                    end
                    raw = S.(name);
                    if islogical(raw) || isnumeric(raw)
                        value = logical(raw(1));
                    else
                        value = any(strcmpi(strtrim(char(string(raw))), {'1','true','yes','on'}));
                    end
                    return;
                catch
                end
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;

            app.AdddataButton.Visible='off';
            app.AddclassifierButton.Visible='off';

            app.UpdaterawdatapathButton.Visible='off';
            app.IdentifyROIsinpositionsButton.Visible='off';
            app.ExtractROIhypervolumesButton.Visible='off';

            app.ClassifydataButton.Visible='off';
            %    app.ClassifyprocessdataLabel.Visible='off';
            %      app.CreateorimportaclassifierprocessorLabel.Visible='off';
            %      app.IdentfyandextractregionsofinterestROIsindataLabel.Visible='off';
            %     app.AdddataintheprojectiepositionLabel.Visible='off';
            %  app.ProjectInformationLabel.Visible='off';
            app.AddprocessorButton.Visible='off';
            app.ProcessdataButton.Visible='off';
            app.InspectRunButton.Visible='off';
            app.setComponentVisible('EditRunButton', 'off');
            app.OpenButton.Visible='off';
            cla( app.UIAxes);
            app.UIAxes.Visible='off';
            app.applyTextOnlyMainPanelLayout();

            % HERE

            if numel(selectedNodes)==0
                return
            end

            if numel(selectedNodes.Tag)==0 & strcmp(selectedNodes.Text,'Projects')
                app.ProjectsPanel.Title='';
                app.ProjectInformationLabel.Text='';
            end
            if numel(selectedNodes.Tag)==0 & any(strcmp(selectedNodes.Text, {'Independent Classifiers','Classifiers'}))
                app.ProjectsPanel.Title='';
                app.ProjectInformationLabel.Text='';
            end
            if strcmp(selectedNodes.Tag,'PipelinesRoot') || (numel(selectedNodes.Tag)==0 & strcmp(selectedNodes.Text,'Pipeline'))
                app.ProjectsPanel.Title='';
                app.ProjectInformationLabel.Text='';
            end

            if strcmp(selectedNodes.Tag,'Project')

                % displayNodes(app)

                app.ProjectsPanel.Title='Project';
                app.ProjectInformationLabel.Text='Loading project information...';
                drawnow;
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};
                shallowObj=evalin('base',proj);

                t='';
                t=[t app.formatProjectHubStatusText(shallowObj) newline newline];
                t=[t 'Project path: ' newline newline];
                t=[t shallowObj.io.path shallowObj.io.file '.mat' newline newline];

                n = getProjectFovCount(app, shallowObj);

                t=[t 'Number of positions: ' num2str(n) newline newline];

                t=[t 'Number of classifiers in project: ' num2str(numel(shallowObj.processing.classification)) newline newline];

                [defaultPipePath, defaultPipeId] = detecdiv_project_cached_pipeline_ref(shallowObj);
                if ~isempty(defaultPipePath) || ~isempty(defaultPipeId)
                    t=[t 'Default pipeline: '];
                    if ~isempty(defaultPipeId)
                        t=[t defaultPipeId newline];
                    else
                        [~, pipeFolder] = fileparts(fileparts(defaultPipePath));
                        t=[t pipeFolder newline];
                    end
                    if ~isempty(defaultPipePath)
                        t=[t defaultPipePath newline];
                    end
                    t=[t newline];
                end

                nRuns = 0;
                if isfield(shallowObj.processing,'pipelineRun')
                    nRuns = numel(shallowObj.processing.pipelineRun);
                    t=[t 'Number of pipeline runs in project: ' num2str(nRuns) newline newline];
                end
                try
                    runTemplates = {};
                    if nRuns > 0
                        runs = shallowObj.processing.pipelineRun;
                        for ir = 1:numel(runs)
                            if isprop(runs(ir),'pipelineRef') && isstruct(runs(ir).pipelineRef)
                                if isfield(runs(ir).pipelineRef,'id') && ~isempty(runs(ir).pipelineRef.id)
                                    runTemplates{end+1} = char(string(runs(ir).pipelineRef.id)); %#ok<AGROW>
                                elseif isfield(runs(ir).pipelineRef,'path') && ~isempty(runs(ir).pipelineRef.path)
                                    [~, nm] = fileparts(char(string(runs(ir).pipelineRef.path)));
                                    runTemplates{end+1} = nm; %#ok<AGROW>
                                end
                            end
                        end
                    end
                    runTemplates = unique(runTemplates, 'stable');
                    if ~isempty(runTemplates)
                        t=[t 'Pipeline templates used in project: ' num2str(numel(runTemplates)) newline];
                        t=[t strjoin(runTemplates, ', ') newline newline];
                    end
                catch
                end
                if nRuns > 0
                    t=[t 'Open a project run to inspect or edit the exact pipeline instance used for that run.' newline];
                end
                t=[t 'Use the Pipeline section for standalone templates.' newline];
                app.ProjectInformationLabel.Text=t;

                app.AdddataButton.Visible='off';
                app.AddclassifierButton.Visible='off';
                app.UpdaterawdatapathButton.Visible='off';
                app.IdentifyROIsinpositionsButton.Visible='off';
                app.ExtractROIhypervolumesButton.Visible='off';

                app.ClassifydataButton.Visible='off';
                app.AddprocessorButton.Visible='off';
                app.ProcessdataButton.Visible='off';

            else

            end

            if strcmp(selectedNodes.Tag,'Projectpos')

                app.ProjectsPanel.Title='Position';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Position...';

                cc=app.Tree.SelectedNodes.UserData;

                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                position=shallowObj.fov(pos);

                app.ensureProjectPositionRoiTreeExpanded(app.Tree.SelectedNodes, shallowObj, cc(1), cc(2));


                % display text

                t='';
                t=[t app.formatProjectHubStatusText(shallowObj) newline newline];
                t=[t 'Source files path: ' newline newline];

                for i=1%:numel(position.srcpath)
                    t=[t position.srcpath{i} newline newline];
                end

                t=[t 'Source sample filename: ' newline newline];
                t=[t app.describeFovSampleSource(position) newline newline];

                t=[t num2str(numel(position.channel)) ' channels: '];

                for i=1:numel(position.channel)
                    t=[t position.channel{i} ' '];
                end

                t=[t newline newline];
                t=[t app.describeFovFrames(position) ' frames' newline newline];

                n=numel(position.roi);
                if n==1 & numel(position.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as available datasets' newline];

                if n==0
                    t=[t 'You must first define one or several ROIs by opening the raw data viewer!' newline newline];
                end
                app.ProjectInformationLabel.Text=t;

            end


            if strcmp(selectedNodes.Tag,'Projectclassi')
                app.ProjectsPanel.Title='Classifier';
                app.applyImageMainPanelLayout();

                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Classifier...';

                app.UIAxes.Visible='on';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);

                if ~app.treeNodeHasChildTag(app.Tree.SelectedNodes, 'Projectclassirois')
                    roiLabels = buildRoiTreeLabels(app, clas.roi);
                    if ~isempty(roiLabels)
                        app.startRoiTreeBuild(app.Tree.SelectedNodes, roiLabels, ...
                            'Projectclassirois', [cc(1), cc(2)]);
                    end
                end


                t='';
                t=[t 'Classification path: ' newline];
                t=[t clas.path newline newline];

                if numel(clas.description)
                    t=[t 'Description: '];

                    t=[t clas.description{1}];
                end

                if numel(clas.category)
                    [catCell, ~] = classiNormalizeCategory(clas.category);
                    t=[t catCell{1} char(13)];
                end

                if numel(clas.classes)
                    t=[t 'Classes: '];

                    for i=1:numel(clas.classes)
                        t=[t clas.classes{i}];
                    end
                end

                t=[t  char(13)];

                n=numel(clas.roi);
                if n==1 & numel(clas.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as training/test sets'  newline];

                if n==0
                    t=[t 'You must first import ROIs ! For this, first open the classifier.' newline newline];
                end

                app.ProjectInformationLabel.Text=t;
                displayClassiImage(app,clas);
            end

            if strcmp(selectedNodes.Tag,'Projectprocess')
                app.ProjectsPanel.Title='Processor';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Processor...';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.processor(pos);

                %                 if numel(app.Tree.SelectedNodes.Children)==0
                %                  if numel(app.Data.Projectclassirois{cc(1)})
                %                  [pth fle ext]= fileparts(which('detecdiv.mlapp'));
                %                     for n=1:numel(app.Data.Projectclassirois{cc(1)}{cc(2)})
                %                       % aa=app.Data.Projectclassirois{i}{k}{n}
                %                       cm=uicontextmenu(app.DetecDivUIFigure);
                %                     m = uimenu(cm,'Text','Open ROI...');
                %                     m.MenuSelectedFcn={@contextMenuROIFcn,[cc(1),cc(2),n],'Projectclassirois'};
                %                   %  ''ContextMenu',cm'
                %                         uitreenode(app.Tree.SelectedNodes,'Text',app.Data.Projectclassirois{cc(1)}{cc(2)}{n},'Tag','Projectclassirois','UserData',[cc(1),cc(2),n],'Icon',fullfile(pth,'roi.png'));
                %                                     % disabled because too heavy with large projects
                %                     end
                %                  end
                %                 end


                t='';
                t=[t 'Processor path: ' newline];
                t=[t clas.path newline newline];

                if isprop(clas, 'description') && ~isempty(clas.description)
                    t=[t 'Description: '];
                    if iscell(clas.description)
                        t=[t char(string(clas.description{1})) newline];
                    else
                        t=[t char(string(clas.description)) newline];
                    end
                end

                if isprop(clas, 'category') && ~isempty(clas.category)
                    if iscell(clas.category)
                        categoryText = clas.category{1};
                    else
                        categoryText = clas.category;
                    end
                    t=[t 'Category: ' char(string(categoryText)) newline];
                end

                if isprop(clas, 'processFun') && ~isempty(clas.processFun)
                    t=[t 'Function: ' char(string(clas.processFun)) newline];
                end


                t=[t  char(13)];


                app.ProjectInformationLabel.Text=t;
            end


            if strcmp(selectedNodes.Tag,'Classifier')
                app.ProjectsPanel.Title='Classifier';
                app.applyImageMainPanelLayout();
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Classifier...';

                app.UIAxes.Visible='on';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{cc};

                clas=evalin('base',proj);


                if ~app.treeNodeHasChildTag(app.Tree.SelectedNodes, 'Classifierrois')
                    roiLabels = buildRoiTreeLabels(app, clas.roi);
                    if ~isempty(roiLabels)
                        app.startRoiTreeBuild(app.Tree.SelectedNodes, roiLabels, ...
                            'Classifierrois', cc);
                    end
                end

                t='';
                t=[t 'Classification path: ' newline newline];
                t=[t clas.path newline newline];

                if numel(clas.description)
                    t=[t 'Description: '];

                    t=[t clas.description{1} ' - '];
                end

                if numel(clas.category)
                    [catCell, ~] = classiNormalizeCategory(clas.category);
                    t=[t catCell{1} newline newline];
                end

                if numel(clas.classes)

                    t=[t 'Classes: ' newline newline];

                    for i=1:numel(clas.classes)
                        t=[t clas.classes{i} newline];
                    end
                end
                t=[t newline];
                n=numel(clas.roi);
                if n==1 & numel(clas.roi(1).id)==0
                    n=0;
                end
                t=[t num2str(n) ' ROIs as training/test sets' newline newline];

                if n==0
                    t=[t 'You must first import ROIs ! For this, first open the classifier.' newline newline];
                end

                app.ProjectInformationLabel.Text=t;

                if numel(clas.category)
                    [catCell, ~] = classiNormalizeCategory(clas.category);
                    if ~strcmp(char(string(catCell{1})),'Timeseries')
                        displayClassiImage(app,clas);
                    end
                end
            end

            if strcmp(selectedNodes.Tag,'Pipeline')
                app.ProjectsPanel.Title='Pipeline';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Pipeline...';
                app.ProcessdataButton.Visible='off';

                idx=app.Tree.SelectedNodes.UserData;
                if idx <= numel(app.Data.Pipeline)
                    pipeVar = app.Data.Pipeline{idx};
                    pipeObj = evalin('base', pipeVar);
                    pipeObj = app.reloadPipelineTemplateFromDiskIfAvailable(pipeObj);
                    try
                        assignin('base', pipeVar, pipeObj);
                    catch
                    end

                    nNodes = 0;
                    nEdges = 0;
                    if isprop(pipeObj,'nodes') && ~isempty(pipeObj.nodes)
                        nNodes = numel(pipeObj.nodes);
                    end
                    if isprop(pipeObj,'edges') && ~isempty(pipeObj.edges)
                        nEdges = numel(pipeObj.edges);
                    end

                    t='';
                    t=[t 'Pipeline variable: ' pipeVar newline newline];
                    t=[t 'Pipeline path: ' newline pipeObj.path newline newline];
                    t=[t 'Nodes: ' num2str(nNodes) newline];
                    t=[t 'Connections: ' num2str(nEdges) newline newline];
                    t=[t app.describePipelineTemplate(pipeObj)];
                    app.ProjectInformationLabel.Text=t;
                end
            end

            if strcmp(selectedNodes.Tag,'PipelineRecent')
                app.ProjectsPanel.Title='Recent pipeline';
                app.OpenButton.Visible='on';
                app.OpenButton.Text='Open Pipeline...';
                app.ProcessdataButton.Visible='off';

                pipelineJson = char(string(selectedNodes.UserData));
                t='';
                t=[t 'Pipeline path: ' newline pipelineJson newline newline];
                if exist(pipelineJson, 'file') ~= 2
                    t=[t 'Status: missing file' newline];
                else
                    try
                        [pipeObj, msg] = pipelineLoad(pipelineJson);
                        if isempty(pipeObj)
                            t=[t 'Status: cannot load pipeline' newline char(string(msg)) newline];
                        else
                            t=[t 'Name: ' char(string(pipeObj.strid)) newline];
                            t=[t 'Nodes: ' num2str(numel(pipeObj.nodes)) newline];
                            t=[t 'Connections: ' num2str(numel(pipeObj.edges)) newline newline];
                            t=[t app.describePipelineTemplate(pipeObj)];
                        end
                    catch ME
                        t=[t 'Status: cannot inspect pipeline' newline ME.message newline];
                    end
                end
                app.ProjectInformationLabel.Text=t;
            end

            if strcmp(selectedNodes.Tag,'PipelineModule')
                app.OpenButton.Visible='on';

                cc=app.Tree.SelectedNodes.UserData;
                pipeIdx = cc(1);
                modIdx = cc(2);

                [ok,node,pipeObj] = app.getPipelineNodeByIndex(pipeIdx, modIdx);
                if ~ok
                    return;
                end
                pipeVar = app.Data.Pipeline{pipeIdx};

                [nodeType, modObj] = app.buildPipelineModuleObject(node);

                if strcmp(nodeType,'classifier')
                    app.ProjectsPanel.Title='Classifier';
                    app.applyImageMainPanelLayout();
                    app.OpenButton.Text='Open Classifier...';
                    app.UIAxes.Visible='on';

                    clas = modObj;
                    t='';
                    t=[t 'Classification path: ' newline newline];
                    if isprop(clas,'path') && ~isempty(clas.path)
                        t=[t clas.path newline newline];
                    else
                        t=[t '(not saved yet)' newline newline];
                    end

                    if isprop(clas,'description') && numel(clas.description)
                        desc = clas.description;
                        if iscell(desc)
                            descTxt = char(string(desc{1}));
                        else
                            descTxt = char(string(desc));
                        end
                        t=[t 'Description: ' descTxt ' - '];
                    end

                    if isprop(clas,'category') && numel(clas.category)
                        [catCell, ~] = classiNormalizeCategory(clas.category);
                        t=[t catCell{1} newline newline];
                    end

                    if isprop(clas,'classes') && numel(clas.classes)
                        t=[t 'Classes: ' newline newline];
                        cls = clas.classes;
                        if isstring(cls), cls = cellstr(cls); end
                        if ischar(cls), cls = {cls}; end
                        for i=1:numel(cls)
                            t=[t char(string(cls{i})) newline];
                        end
                    end

                    t=[t newline];
                    if isprop(clas,'roi')
                        n=numel(clas.roi);
                        if n==1 && numel(clas.roi(1).id)==0
                            n=0;
                        end
                    else
                        n=0;
                    end
                    t=[t num2str(n) ' ROIs as training/test sets' newline newline];
                    app.ProjectInformationLabel.Text=t;

                    try
                        [catCell, ~] = classiNormalizeCategory(clas.category);
                        hasRoi = false;
                        if isprop(clas,'roi') && ~isempty(clas.roi)
                            hasRoi = ~(numel(clas.roi)==1 && isempty(clas.roi(1).id));
                        end
                        if hasRoi && ~strcmp(char(string(catCell{1})),'Timeseries')
                            displayClassiImage(app,clas);
                        else
                            cla(app.UIAxes);
                        end
                    catch
                        cla(app.UIAxes);
                    end

                elseif strcmp(nodeType,'processor')
                    app.ProjectsPanel.Title='Processor';
                    app.OpenButton.Text='Open Processor...';
                    app.UIAxes.Visible='off';

                    proc = modObj;
                    t='';
                    t=[t 'Processor path: ' newline newline];
                    if isprop(proc,'path') && ~isempty(proc.path)
                        t=[t proc.path newline newline];
                    else
                        t=[t '(not saved yet)' newline newline];
                    end

                    if isfield(node,'pkg') && ~isempty(node.pkg)
                        t=[t 'Package: ' char(string(node.pkg)) newline];
                    end
                    if isprop(proc,'processFun') && ~isempty(proc.processFun)
                        t=[t 'Function: ' char(string(proc.processFun)) newline];
                    end

                    if isprop(proc,'processArg') && isstruct(proc.processArg)
                        fn = fieldnames(proc.processArg);
                        if ~isempty(fn)
                            t=[t newline 'Parameters: ' num2str(numel(fn)) newline];
                            nShow = min(numel(fn), 8);
                            for ii=1:nShow
                                t=[t '- ' fn{ii} newline];
                            end
                            if numel(fn) > nShow
                                t=[t '...'];
                            end
                        end
                    end

                    app.ProjectInformationLabel.Text=t;

                else
                    app.ProjectsPanel.Title='Pipeline module';
                    app.OpenButton.Text='Open Pipeline...';
                    app.UIAxes.Visible='off';

                    t='';
                    t=[t 'Pipeline: ' pipeVar newline newline];
                    if isfield(node,'id'), t=[t 'Module id: ' char(string(node.id)) newline]; end
                    if isfield(node,'name'), t=[t 'Name: ' char(string(node.name)) newline]; end
                    if isfield(node,'type'), t=[t 'Type: ' char(string(node.type)) newline]; end
                    if isfield(node,'pkg') && ~isempty(node.pkg), t=[t 'Package: ' char(string(node.pkg)) newline]; end
                    if isfield(node,'func') && ~isempty(node.func), t=[t 'Function: ' char(string(node.func)) newline]; end
                    app.ProjectInformationLabel.Text=t;
                end
            end

            if strcmp(selectedNodes.Tag,'ProjectpipelineRun')
                app.ProjectsPanel.Title='Pipeline run';
                app.InspectRunButton.Visible='on';
                app.InspectRunButton.Text='Review run...';
                app.setComponentVisible('EditRunButton', 'on');
                app.setComponentText('EditRunButton', 'Edit run...');
                app.OpenButton.Visible='on';
                app.OpenButton.Text='New run from this...';

                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                shallowObj=evalin('base',proj);
                if isfield(shallowObj.processing,'pipelineRun') && cc(2) <= numel(shallowObj.processing.pipelineRun)
                    runObj = shallowObj.processing.pipelineRun(cc(2));
                    t='';
                    t=[t 'Run id: ' runObj.runId newline newline];
                    [runMode, runStatus] = app.summarizePipelineRun(runObj);
                    t=[t 'Execution: ' runMode newline];
                    t=[t 'Status: ' runStatus newline newline];
                    t=[t 'Review run opens the immutable execution record and artifacts.' newline];
                    t=[t 'Edit run reopens this run configuration for rerun/update.' newline];
                    t=[t 'New run from this opens the pipeline editor with these parameters as a starting point.' newline newline];
                    if isprop(runObj,'description') && ~isempty(runObj.description)
                        t=[t 'Description: ' char(string(runObj.description)) newline newline];
                    end
                    if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef)
                        if isfield(runObj.pipelineRef,'id')
                            t=[t 'Pipeline id: ' char(string(runObj.pipelineRef.id)) newline];
                        end
                        if isfield(runObj.pipelineRef,'path')
                            t=[t 'Pipeline path: ' char(string(runObj.pipelineRef.path)) newline];
                        end
                    end
                    try
                        if isstruct(runObj.ctx) && isfield(runObj.ctx,'io') && isstruct(runObj.ctx.io)
                            if isfield(runObj.ctx.io,'existingPolicy') && ~isempty(runObj.ctx.io.existingPolicy)
                                t=[t 'Existing policy: ' char(string(runObj.ctx.io.existingPolicy)) newline];
                            end
                            if isfield(runObj.ctx.io,'cachePolicy') && ~isempty(runObj.ctx.io.cachePolicy)
                                t=[t 'Cache policy: ' char(string(runObj.ctx.io.cachePolicy)) newline];
                            end
                        end
                    catch
                    end
                    if isprop(runObj,'targetRef') && isstruct(runObj.targetRef)
                        if isfield(runObj.targetRef,'type')
                            t=[t newline 'Target type: ' char(string(runObj.targetRef.type)) newline];
                        end
                    end
                    try
                        if isprop(runObj,'createdAt') && ~isempty(runObj.createdAt)
                            t=[t 'Created: ' char(string(runObj.createdAt)) newline];
                        end
                        if isprop(runObj,'updatedAt') && ~isempty(runObj.updatedAt)
                            t=[t 'Updated: ' char(string(runObj.updatedAt)) newline];
                        end
                    catch
                    end
                    t=[t newline 'Open Review run for the event log and live Hub status.' newline];
                    app.ProjectInformationLabel.Text=t;
                end
            end

            if strcmp(selectedNodes.Tag,'ClassifierpipelineRun')
                app.ProjectsPanel.Title='Pipeline run';
                app.InspectRunButton.Visible='on';
                app.InspectRunButton.Text='Review run...';
                app.setComponentVisible('EditRunButton', 'on');
                app.setComponentText('EditRunButton', 'Edit run...');
                app.OpenButton.Visible='on';
                app.OpenButton.Text='New run from this...';

                cc=app.Tree.SelectedNodes.UserData;
                [runObj, clas] = app.getClassifierPipelineRun(cc(1), cc(2));
                if ~isempty(runObj)
                    t='';
                    t=[t 'Run id: ' runObj.runId newline newline];
                    [runMode, runStatus] = app.summarizePipelineRun(runObj);
                    t=[t 'Execution: ' runMode newline];
                    t=[t 'Status: ' runStatus newline newline];
                    t=[t 'Attached classifier: ' app.Data.Classifier{cc(1)} newline];
                    try
                        if ~isempty(clas) && isprop(clas,'path')
                            t=[t 'Classifier path: ' char(string(clas.path)) newline];
                        end
                    catch
                    end
                    try
                        [runPath, ~] = runObj.getPath;
                        t=[t 'Run path: ' runPath newline newline];
                    catch
                        t=[t newline];
                    end
                    t=[t 'Review run opens the immutable execution record and artifacts.' newline];
                    t=[t 'Edit run reopens this run configuration in pipeline2.' newline];
                    t=[t 'New run from this reloads the complete pipeline template and copies this run selection.' newline newline];
                    if isprop(runObj,'description') && ~isempty(runObj.description)
                        t=[t 'Description: ' char(string(runObj.description)) newline newline];
                    end
                    if isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef)
                        if isfield(runObj.pipelineRef,'id')
                            t=[t 'Pipeline id: ' char(string(runObj.pipelineRef.id)) newline];
                        end
                        if isfield(runObj.pipelineRef,'path')
                            t=[t 'Pipeline path: ' char(string(runObj.pipelineRef.path)) newline];
                        end
                    end
                    t=[t newline 'Open Review run for the event log and live Hub status.' newline];
                    app.ProjectInformationLabel.Text=t;
                end
            end

            if strcmp(selectedNodes.Tag,'Projectposrois') || strcmp(selectedNodes.Tag,'Projectclassirois') || strcmp(selectedNodes.Tag,'Classifierrois')

                arg=app.Tree.SelectedNodes.UserData;

                if strcmp(selectedNodes.Tag,'Projectclassirois')
                    cc=arg(1);
                    pos=arg(2);
                    ro=arg(3);


                    proj=app.Data.Project{cc};
                    shallowObj=evalin('base',proj);
                    roiObj=shallowObj.processing.classification(pos).roi(ro);
                    roiObj.parent=shallowObj.processing.classification(pos);
                end

                if strcmp(selectedNodes.Tag,'Projectposrois')
                    cc=arg(1);
                    pos=arg(2);
                    ro=arg(3);
                    proj=app.Data.Project{cc};
                    shallowObj=evalin('base',proj);
                    roiObj=shallowObj.fov(pos).roi(ro);
                    roiObj.parent=shallowObj.fov(pos);
                end

                if strcmp(selectedNodes.Tag,'Classifierrois')
                    cc=arg(1);
                    ro=arg(2);
                    clas=app.Data.Classifier{cc};
                    clas=evalin('base',clas);

                    roiObj=clas.roi(ro);

                    roiObj.parent=clas;
                end


                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Loading selected ROI...');
                d.Value=0.33;

                %roiObj.view;

                if numel(roiObj.image)==0

                    roiObj.load;
                end

                if numel(roiObj.image)==0
                    disp('Cannot open ROI obj; Quitting...')
                    return;
                end

                switch selectedNodes.Tag
                    case 'Projectclassirois'
                        roiObj.parent=shallowObj.processing.classification(pos);
                    case 'Projectposrois'
                        roiObj.parent=shallowObj.fov(pos);
                    case 'Classifierrois'
                        roiObj.parent=clas;
                end

                try
                    if isfield(roiObj,'display') && isstruct(roiObj.display) ...
                            && isfield(roiObj.display,'channel') && ~isempty(roiObj.display.channel)
                        nLog = numel(roiObj.display.channel);
                        if ~isfield(roiObj.display,'indexed') || isempty(roiObj.display.indexed)
                            roiObj.display.indexed = zeros(1, nLog);
                        elseif numel(roiObj.display.indexed) < nLog
                            roiObj.display.indexed(end+1:nLog) = 0;
                        end
                        for iLog = 1:nLog
                            chName = lower(string(roiObj.display.channel{iLog}));
                            isMaskLike = startsWith(chName, "results_") || contains(chName, "mask") || contains(chName, "track");
                            if ~isMaskLike
                                continue;
                            end
                            if isfield(roiObj.display,'intensity') && size(roiObj.display.intensity,1) >= iLog
                                row = double(roiObj.display.intensity(iLog,:));
                                if all(row == 0)
                                    roiObj.display.indexed(iLog) = 1;
                                end
                            else
                                roiObj.display.indexed(iLog) = 1;
                            end
                        end
                    end
                catch
                end

                try
                    clear score score_display score_updateHistogram score_updateIntensityProfile score_updateEllipticalProfile
                    rehash
                catch
                end

                openedInExistingScore = false;
                projectArg = {};
                if exist('shallowObj', 'var') && isa(shallowObj, 'shallow')
                    projectArg = {'Project', shallowObj};
                end
                try
                    figures=findall(0,'Type','figure');
                    appFigure=findobj(figures,'Name','ScoreApp');
                    if ~isempty(appFigure) && isprop(appFigure(1),'RunningAppInstance')
                        scoreApp = appFigure(1).RunningAppInstance;
                        if ~isempty(scoreApp) && isvalid(scoreApp)
                            scoreApp.addROI(roiObj, '', projectArg{:});
                            try
                                figure(scoreApp.ScoreAppUIFigure);
                            catch
                            end
                            openedInExistingScore = true;
                        end
                    end
                catch ME
                    warning('DetecDiv:OpenScore:AddROI', ...
                        'Could not add ROI to existing Score instance: %s', ME.message);
                    openedInExistingScore = false;
                end

                if ~openedInExistingScore
                    try
                        clear score
                        rehash
                    catch
                    end
                    score(roiObj, '', projectArg{:});
                end

                d.Value=0.67;

                if numel(roiObj.image)==0
                    d.Message='Cannot access ROI file, or ROI data have not yet been extracted... Quitting!';
                    pause(2);
                else
                    d.Message='Loading ROI successful!';
                end
                pause(0.2);
                close(d)
            end


        end

        % Callback function
        function ContextMenuOpening(app, event)

        end

        % Callback function
        function OpenMenuSelected(app, event)

        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
            proj=shallowNew;
            if numel(proj)==0
                return;
            end
            name=makeSafeVariableName(proj.io.file);
            assignin('base',name,proj);
            gatherVarsFromWorkspace(app);
            displayNodes(app)
        end

        % Callback function
        function DetecDivUIFigureWindowButtonDown(app, event)

        end

        % Callback function
        function DetecDivUIFigureButtonDown(app, event)

        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)

            d = uiprogressdlg(app.DetecDivUIFigure, ...
                'Title','Please Wait...', ...
                'Message','Loading selected project...');

            d.Value = 0.33;

            [proj, msg] = shallowLoad;
            if isempty(proj)
                close(d);
                if ~isempty(msg)
                    uialert(app.DetecDivUIFigure, msg, 'Warning', 'Icon', 'warning');
                end
                return;
            end
            d.Message = 'Checking Hub access and acquiring a local edit lease...';
            drawnow;
            [proj, hubAccess] = detecdiv_hub_prepare_project_open(proj);

            d.Value = 0.66;
            pause(0.2);  % (garde si tu veux forcer l'update graphique)

            % mettre l'objet dans le workspace base sous son nom
            name = makeSafeVariableName(proj.io.file);
            assignin('base', name, proj);

            % chemin absolu du .mat du projet

            projectPathChar = fullfile(proj.io.path, [proj.io.file '.mat']);
            app.registerRecentProject(string(projectPathChar));

            % mettre à jour l'état interne de l'app
            gatherVarsFromWorkspace(app);
            displayNodes(app);
            if exist('hubAccess', 'var') && hubAccess.hubManaged && hubAccess.readOnly
                uialert(app.DetecDivUIFigure, hubAccess.reason, 'Hub project opened read-only', 'Icon', 'warning');
            end

            % éventuellement, vérifier les chemins images (tu l'as commenté)
            % check = checkImagePath(app,proj);
            % if any(check)==0
            %     warnmsg = ['Those positions have an incorrect base path.' newline ...
            %                'You must update the path if you plan to build new region of interest / process the raw data !'];
            %     uialert(app.DetecDivUIFigure, warnmsg, 'Warning', 'Icon','warning');
            % end

            close(d);


        end

        % Menu selected function: Catalog Browser
        function CatalogBrowserMenuSelected(app, event) %#ok<INUSD>
            progressDlg = [];
            try
                progressDlg = uiprogressdlg(app.DetecDivUIFigure, ...
                    'Title', 'Opening Catalog Browser', ...
                    'Message', 'Preparing catalog browser...', ...
                    'Indeterminate', 'on', ...
                    'Cancelable', 'off');
                drawnow;
                if exist('launch_catalog_browser', 'file') == 2
                    launch_catalog_browser;
                else
                    detecdivCatalogBrowser;
                end
                try
                    if ~isempty(progressDlg) && isvalid(progressDlg)
                        close(progressDlg);
                    end
                catch
                end
            catch ME
                try
                    if ~isempty(progressDlg) && isvalid(progressDlg)
                        close(progressDlg);
                    end
                catch
                end
                uialert(app.DetecDivUIFigure, ME.message, 'Catalog Browser', 'Icon', 'error');
            end
        end

        % Menu selected function: NewpipelinetemplateMenu
        function NewpipelinetemplateMenuSelected(app, event) %#ok<INUSD>
            parentDir = uigetdir(pwd, 'Select parent folder where pipeline folder will be created');
            if isequal(parentDir,0)
                return;
            end

            answer = inputdlg({'Pipeline template name:'}, 'New pipeline template', [1 60], {'pipeline'});
            if isempty(answer)
                return;
            end
            pipeName = strtrim(answer{1});
            if isempty(pipeName)
                pipeName = ['pipeline_' char(datetime('now','Format','yyyyMMdd_HHmmss'))];
            end

            try
                pipeObj = pipelineNew('path', parentDir, 'name', pipeName, 'workspace', true);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Error', 'Icon', 'error');
                return;
            end

            if isempty(pipeObj)
                return;
            end

            app.registerRecentPipeline(string(fullfile(pipeObj.path, 'pipeline.json')));

            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Menu selected function: OpenpipelinetemplateMenu
        function OpenpipelinetemplateMenuSelected(app, event) %#ok<INUSD>
            [file, path] = uigetfile('*.json', 'Select pipeline.json', pwd);
            if isequal(file, 0)
                return;
            end
            pipelineJsonPath = fullfile(path, file);

            d = uiprogressdlg(app.DetecDivUIFigure, ...
                'Title', 'Please wait', ...
                'Message', 'Loading pipeline template...', ...
                'Indeterminate', 'on');
            cleanupObj = onCleanup(@()app.safeCloseProgressDialog(d)); %#ok<NASGU>
            drawnow limitrate nocallbacks;

            [pipeObj, msg] = pipelineLoad(pipelineJsonPath);
            if isempty(pipeObj)
                if ~isempty(msg)
                    uialert(app.DetecDivUIFigure, msg, 'Warning', 'Icon', 'warning');
                end
                return;
            end

            app.updatePipelineOpenProgress(d, 'Registering pipeline in workspace...');
            assignin('base', app.nextPipelineVarName(pipeObj), pipeObj);
            app.registerRecentPipeline(string(pipelineJsonPath));

            app.updatePipelineOpenProgress(d, 'Refreshing project tree...');
            gatherVarsFromWorkspace(app);
            displayNodes(app);
            try
                app.selectPipelineNodeByJsonPath(pipelineJsonPath);
            catch
            end
            app.updatePipelineOpenProgress(d, 'Opening pipeline editor...');
            app.openPipelineWithContext(pipeObj, d);
        end

        % Menu selected function: ClosepipelinetemplateMenu
        function ClosepipelinetemplateMenuSelected(app, event) %#ok<INUSD>
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a pipeline in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Pipeline')
                uialert(app.DetecDivUIFigure,'The selected node is not a pipeline template!','Error');
                return;
            end

            idx = app.Tree.SelectedNodes.UserData;
            if idx > numel(app.Data.Pipeline)
                return;
            end

            varName = app.Data.Pipeline{idx};
            evalin('base', ['clear ' varName]);

            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Menu selected function: Closeproject
        function CloseprojectMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};
                closeDialog = uiprogressdlg(app.DetecDivUIFigure, ...
                    'Title', 'Closing project', ...
                    'Message', 'Releasing the Hub edit lease...', ...
                    'Indeterminate', 'on');
                closeGuard = onCleanup(@() close(closeDialog)); %#ok<NASGU>
                drawnow;

                shallowObj = [];
                try
                    shallowObj = evalin('base', proj);
                catch
                end
                try
                    if ~isempty(shallowObj)
                        closeHub = detecdiv_hub_settings_get();
                        closeHub.timeout = min(5, double(closeHub.timeout));
                        detecdiv_hub_release_project_open(shallowObj, closeHub);
                    end
                catch
                end

                closeDialog.Message = 'Removing the project and linked pipelines from this session...';
                drawnow;
                clearVars = {proj};
                if isfield(app.Data,'Pipeline') && ~isempty(app.Data.Pipeline)
                    clearVars = [clearVars localFindLinkedPipelineVars(app, shallowObj, proj)]; %#ok<AGROW>
                end
                clearVars = unique(clearVars, 'stable');
                evalin('base',['clear ' strjoin(clearVars,' ')]);
                gatherVarsFromWorkspace(app);
                displayNodes(app)
            end
        end

        function vars = localFindLinkedPipelineVars(app, shallowObj, projVar)
            vars = {};
            if ~isfield(app.Data,'Pipeline') || isempty(app.Data.Pipeline)
                return;
            end

            projectRoot = "";
            linkedPipelineRoots = strings(0);
            if ~isempty(shallowObj)
                try
                    projectRoot = localNormalizeFsPath(fullfile(shallowObj.io.path, shallowObj.io.file));
                catch
                end
                try
                    defaultRoot = string(app.normalizePipelineRootPath(app.getProjectDefaultPipelinePath(shallowObj)));
                    if strlength(defaultRoot) > 0
                        linkedPipelineRoots(end+1) = defaultRoot; %#ok<AGROW>
                    end
                catch
                end
                try
                    runs = shallowObj.processing.pipelineRun;
                catch
                    runs = [];
                end
                for iRun = 1:numel(runs)
                    runPath = '';
                    try
                        if isprop(runs(iRun),'pipelineRef') && isstruct(runs(iRun).pipelineRef) ...
                                && isfield(runs(iRun).pipelineRef,'path') && ~isempty(runs(iRun).pipelineRef.path)
                            runPath = char(string(runs(iRun).pipelineRef.path));
                        end
                        if isempty(runPath) && isprop(runs(iRun),'templatePath') && ~isempty(runs(iRun).templatePath)
                            runPath = char(string(runs(iRun).templatePath));
                        end
                    catch
                        runPath = '';
                    end
                    root = string(app.normalizePipelineRootPath(runPath));
                    if strlength(root) > 0
                        linkedPipelineRoots(end+1) = root; %#ok<AGROW>
                    end
                end
                linkedPipelineRoots = unique(linkedPipelineRoots, 'stable');
            end

            prefix = [char(string(projVar)) '_pipeline'];
            for idx = 1:numel(app.Data.Pipeline)
                pipeVar = app.Data.Pipeline{idx};
                if isempty(pipeVar)
                    continue;
                end

                pipeVarChar = char(string(pipeVar));
                if strcmp(pipeVarChar, prefix) || startsWith(pipeVarChar, [prefix '_'], 'IgnoreCase', true)
                    vars{end+1} = pipeVarChar; %#ok<AGROW>
                    continue;
                end

                try
                    pipeObj = evalin('base', pipeVarChar);
                catch
                    continue;
                end

                try
                    pipePath = localNormalizeFsPath(pipeObj.path);
                    pipeRoot = string(app.normalizePipelineRootPath(pipeObj.path));
                catch
                    pipePath = "";
                    pipeRoot = "";
                end

                if strlength(projectRoot) > 0 && strlength(pipePath) > 0 && ...
                        startsWith(pipePath, projectRoot, 'IgnoreCase', true)
                    vars{end+1} = pipeVarChar; %#ok<AGROW>
                    continue;
                end

                if strlength(pipeRoot) > 0 && any(strcmp(linkedPipelineRoots, pipeRoot))
                    vars{end+1} = pipeVarChar; %#ok<AGROW>
                end
            end
        end

        function out = localNormalizeFsPath(p)
            out = string(p);
            if strlength(out) == 0
                return;
            end
            out = replace(out, '/', filesep);
            out = replace(out, '\', filesep);
            while endsWith(out, filesep) && strlength(out) > 3
                out = extractBefore(out, strlength(out));
            end
        end

        function saveShallowProjectWithProgress(app, shallowObj, message, option)
            if nargin < 3 || isempty(message)
                message = 'Saving project...';
            end
            if nargin < 4
                option = [];
            end

            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            d = [];
            try
                d = uiprogressdlg(app.DetecDivUIFigure, ...
                    'Title', 'Save project', ...
                    'Message', message, ...
                    'Value', 0.05);
                drawnow;
            catch
                d = [];
            end
            cleanupDlg = onCleanup(@() app.closeProgressDialog(d)); %#ok<NASGU>

            if isempty(d)
                shallowSave(shallowObj, option);
            else
                shallowSave(shallowObj, option, d);
            end
        end

        function closeProgressDialog(app, d) %#ok<INUSD>
            try
                if ~isempty(d) && isvalid(d)
                    close(d);
                end
            catch
            end
        end

        function label = describeFovSampleSource(app, position) %#ok<INUSD>
            label = '(source metadata only)';
            try
                if isprop(position,'srclist') && iscell(position.srclist) && ...
                        ~isempty(position.srclist) && ~isempty(position.srclist{1}) && ...
                        isfield(position.srclist{1}, 'name') && ~isempty(position.srclist{1}(1).name)
                    label = position.srclist{1}(1).name;
                    return;
                end
            catch
            end

            try
                if isprop(position,'isOMEZarr') && position.isOMEZarr
                    seriesName = '';
                    arrayPath = '';
                    if isprop(position,'omeZarrSeries'), seriesName = char(string(position.omeZarrSeries)); end
                    if isprop(position,'omeZarrArrayPath'), arrayPath = char(string(position.omeZarrArrayPath)); end
                    label = ['OME-Zarr dataset'];
                    if ~isempty(seriesName) || ~isempty(arrayPath)
                        label = [label ' (' seriesName '/' arrayPath ')'];
                    end
                    return;
                end
                if isprop(position,'isNDTiff') && position.isNDTiff
                    label = 'NDTiff dataset';
                    return;
                end
                if isprop(position,'isMultiTiff') && position.isMultiTiff
                    label = 'multi-TIFF stack';
                    try
                        if ~isempty(position.tiffSource) && ~isempty(position.tiffSource{1})
                            [~, nm, ext] = fileparts(position.tiffSource{1});
                            label = [nm ext];
                        end
                    catch
                    end
                    return;
                end
            catch
            end
        end

        function txt = describeFovFrames(app, position) %#ok<INUSD>
            txt = 'unknown';
            try
                if isprop(position,'frames') && ~isempty(position.frames)
                    txt = num2str(position.frames);
                    return;
                end
                if isprop(position,'srclist') && iscell(position.srclist) && ...
                        ~isempty(position.srclist) && ~isempty(position.srclist{1})
                    txt = num2str(numel(position.srclist{1}));
                end
            catch
            end
        end

        % Menu selected function: SaveselectedprojectMenu
        function SaveselectedprojectMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                shallowObj=evalin('base',proj);

                if numel(shallowObj)==0
                    uialert(app.DetecDivUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                end
                try
                    detecdiv_hub_assert_project_writable(shallowObj);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Project is read-only', 'Icon', 'warning');
                    return;
                end

                app.saveShallowProjectWithProgress(shallowObj, 'Saving selected project...');
            else
                uialert(app.DetecDivUIFigure,'No project was selected in the window!','Error','Icon','warning');
            end

        end

        % Menu selected function: ReloadprojectfromdiskMenu
        function ReloadprojectfromdiskMenuSelected(app, event) %#ok<INUSD>
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'Select a project node to reload it from disk.','Error','Icon','warning');
                return;
            end

            i = app.Tree.SelectedNodes.UserData;
            projVar = app.Data.Project{i};
            shallowObj = evalin('base', projVar);

            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                uialert(app.DetecDivUIFigure,'This project does not exist in the workspace.','Error','Icon','warning');
                return;
            end

            projectPathChar = app.getProjectMatPathFromObject(shallowObj);
            if isempty(projectPathChar) || exist(projectPathChar, 'file') ~= 2
                uialert(app.DetecDivUIFigure, ...
                    sprintf('Project file not found:\n%s', projectPathChar), ...
                    'Missing project file', ...
                    'Icon','warning');
                return;
            end

            selection = uiconfirm(app.DetecDivUIFigure, ...
                sprintf(['Reload selected project from disk?\n\n' ...
                'This will replace the in-memory object "%s" with:\n%s'], ...
                projVar, projectPathChar), ...
                'Reload project', ...
                'Options', {'Reload','Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2, ...
                'Icon', 'warning');

            if ~strcmp(selection, 'Reload')
                return;
            end

            openRecentProjectCallback(app, projectPathChar);
        end

        % Button pushed function: AddclassifierButton
        function AddclassifierButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};

                shallowObj=evalin('base',proj);
                app.openLatestProjectRunOrPipeline(shallowObj);
                return;
            end
        end

        % Button pushed function: AdddataButton
        function AdddataButtonPushed(app, event)

            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end
            i=app.Tree.SelectedNodes.UserData;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            app.openProjectPipelineTemplate(shallowObj);

            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);

        end

        % Button pushed function: UpdaterawdatapathButton
        function UpdaterawdatapathButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            app.createProjectPipelineTemplate(shallowObj);
            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);

        end

        function ForcerawdatapathrebaseMenuSelected(app, event) %#ok<INUSD>
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'Select a project node before forcing raw-data rebase.','Error','Icon','warning');
                return;
            end

            i = app.Tree.SelectedNodes.UserData;
            proj = app.Data.Project{i};
            shallowObj = evalin('base',proj);

            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                uialert(app.DetecDivUIFigure,'This project does not exist in the workspace.','Error','Icon','warning');
                return;
            end

            selection = uiconfirm(app.DetecDivUIFigure, ...
                ['Force raw-data path rebase for the selected project?' newline newline ...
                 'This recalculates FOV source paths from a RAWDATA root even when the current paths are still reachable.'], ...
                'Force raw-data rebase', ...
                'Options', {'Rebase','Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2, ...
                'Icon', 'warning');

            if ~strcmp(selection, 'Rebase')
                return;
            end

            try
                [~, report] = detecdiv_paths_relink_project(shallowObj, 'Force', true);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Force raw-data rebase failed', 'Icon', 'error');
                return;
            end

            if isempty(report)
                uialert(app.DetecDivUIFigure, 'No FOV/channel entry was rebased.', 'Force raw-data rebase', 'Icon', 'warning');
                return;
            end

            okCount = sum([report.ok]);
            totalCount = numel(report);
            changed = false;
            try
                changed = any(~strcmp({report.before}, {report.after}));
            catch
            end

            msg = sprintf('Raw-data rebase done: %d/%d channel entries valid.', okCount, totalCount);
            if changed
                saveChoice = uiconfirm(app.DetecDivUIFigure, ...
                    [msg newline newline 'Save the project file now?'], ...
                    'Force raw-data rebase', ...
                    'Options', {'Save project','Do not save'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 2, ...
                    'Icon', 'info');
                if strcmp(saveChoice, 'Save project')
                    try
                        detecdiv_hub_assert_project_writable(shallowObj);
                        app.saveShallowProjectWithProgress(shallowObj, 'Saving rebased project...');
                    catch ME
                        uialert(app.DetecDivUIFigure, ME.message, 'Save error', 'Icon', 'error');
                    end
                end
            else
                uialert(app.DetecDivUIFigure, msg, 'Force raw-data rebase', 'Icon', 'success');
            end

            checkImagePath(app,shallowObj);
            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);

        end

        % Callback function
        function CheckrawdatapathButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            check = checkImagePath(app,shallowObj);
        end

        % Menu selected function: NewprojectindependentclassiiferMenu
        function NewprojectindependentclassiiferMenuSelected(app, event)
            %classifierGUI([],app)
            classifierImporterGUI(app,[])
            uiwait(app.DetecDivUIFigure)
            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Menu selected function: OpenprojectindependentclassifierMenu
        function OpenprojectindependentclassifierMenuSelected(app, event)
% Menu selected function: OpenprojectindependentclassifierMenu
    d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
        'Message','Loading selected classifier...');
    d.Value = 0.33;

    [classiObj, msg] = classiLoad;

    if isempty(classiObj)
        close(d);
        if ~isempty(msg)
            uialert(app.DetecDivUIFigure,msg,'Warning','Icon','warning');
        end
        return;
    end

    d.Value = 0.66;
    pause(0.2);

    % Publier dans le workspace
    name = [classiObj.strid '_indep'];
    assignin('base', name, classiObj);

    % Construire le chemin absolu du fichier .mat de ce classi,
    % pour l'ajouter à la liste des récents.
    % classiLoad a déjà fait:
    %   path = abspath;
    %   file = ...'_classification';
    %   classiObj.setPath([path '\'], file);
    %
    % Du coup:
    classiMatPath = fullfile(classiObj.path, [classiObj.strid '_classification.mat']);
    % Attention : classiObj.path finit avec '\' (ou '/'), et strid n'inclut pas "_classification" ?
    % On doit être 100% sûr. Sinon, plus robuste:
    %   classiObj.path -> "...\classification\yolo_1\"
    %   classiObj.strid -> "yolo_1"
    %   le fichier réel -> "yolo_1_classification.mat"
    %
    % Donc mieux :
    classiMatFilename = [classiObj.strid '_classification.mat'];
    classiMatFull = fullfile(classiObj.path, classiMatFilename);

    app.registerRecentClassifier(classiMatFull);

    gatherVarsFromWorkspace(app);
    displayNodes(app);

    close(d);

        end

        % Menu selected function: CloseprojectindependentclassifierMenu
        function CloseprojectindependentclassifierMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Classifier')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{i};

                evalin('base',['clear ' proj]);
                gatherVarsFromWorkspace(app);
                displayNodes(app)
            end
        end

        % Menu selected function: SaveprojectindependentclassifierMenu
        function SaveprojectindependentclassifierMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Classifier')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{i};

                classiObj=evalin('base',proj);

                if numel(classiObj)==0
                    uialert(app.DetecDivUIFigure,'This project does not exist in the worksapce','Error','Icon','warning');
                    return;
                end

                d = uiprogressdlg(app.DetecDivUIFigure,'Title','Please Wait...',...
                    'Message','Saving selected project...');
                d.Value=0.1;
                pause(0.5)
                classiSave(classiObj);
                d.Value=0.9;
                close(d);
            end

        end

        % Button pushed function: IdentifyROIsinpositionsButton
        function IdentifyROIsinpositionsButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end
            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i = app.Tree.SelectedNodes.UserData;
            proj = app.Data.Project{i};
            shallowObj = evalin('base', proj);

            if numel(shallowObj.fov)==1 && numel(shallowObj.fov(1).srcpath)==0
                uialert(app.DetecDivUIFigure,'First add data before defining or generating ROIs.','Error');
                return;
            end

            options = { ...
                'Pattern calibration + detection', ...
                'Manual ROI drawing', ...
                'Full-frame / grid ROIs', ...
                'Tracked ROIs from masks'};
            [sel, ok] = listdlg('ListString', options, ...
                'SelectionMode', 'single', ...
                'PromptString', 'Choose how to define ROIs for this project:', ...
                'ListSize', [260 130], ...
                'Name', 'ROI workflow');
            if ~ok || isempty(sel)
                return;
            end

            switch sel
                case 1
                    params = app.getProjectDefaultPipelineNodeParams(shallowObj, 'roiPattern');
                    ctx = struct('shallow', shallowObj);
                    if ~isempty(fieldnames(params))
                        ctx.roiPattern = params;
                        ctx.params = params;
                    end
                    ctx = roiPattern.ui(ctx);
                    if isfield(ctx,'cancelled') && ctx.cancelled
                        return;
                    end
                    if ~isfield(ctx,'roiPattern') || ~isstruct(ctx.roiPattern)
                        return;
                    end

                    app.applyProjectRoiProducerChoice(shallowObj, 'roiPattern', ctx.roiPattern);
                    RefreshtreewindowMenuSelected(app, event);

                case 2
                    params = app.getProjectDefaultPipelineNodeParams(shallowObj, 'roiManual');
                    ctx = struct('shallow', shallowObj);
                    if ~isempty(fieldnames(params))
                        ctx.roiManual = params;
                        ctx.params = params;
                    end
                    ctx = roiManual.ui(ctx);
                    if isfield(ctx,'cancelled') && ctx.cancelled
                        return;
                    end
                    if ~isfield(ctx,'roiManual') || ~isstruct(ctx.roiManual)
                        return;
                    end

                    app.applyProjectRoiProducerChoice(shallowObj, 'roiManual', ctx.roiManual);

                    try
                        runCtx = struct('shallow', shallowObj, 'roiManual', ctx.roiManual, 'params', ctx.roiManual);
                        roiManual.process(runCtx);
                        uialert(app.DetecDivUIFigure, ...
                            'Use the raw data viewer to draw or edit ROIs manually, then save/close the viewer when done.', ...
                            'Manual ROI mode', 'Icon', 'info');
                    catch ME
                        uialert(app.DetecDivUIFigure, ME.message, 'Manual ROI setup failed', 'Icon', 'error');
                        return;
                    end

                    RefreshtreewindowMenuSelected(app, event);

                case 3
                    params = app.getProjectDefaultPipelineNodeParams(shallowObj, 'roiGrid');
                    ctx = struct('shallow', shallowObj);
                    if ~isempty(fieldnames(params))
                        ctx.roiGrid = params;
                        ctx.params = params;
                    end
                    ctx = roiGrid.ui(ctx);
                    if isfield(ctx,'cancelled') && ctx.cancelled
                        return;
                    end
                    if ~isfield(ctx,'roiGrid') || ~isstruct(ctx.roiGrid)
                        return;
                    end

                    app.applyProjectRoiProducerChoice(shallowObj, 'roiGrid', ctx.roiGrid);

                    d = uiprogressdlg(app.DetecDivUIFigure, ...
                        'Title', 'Please Wait...', ...
                        'Message', 'Generating grid ROIs...');
                    try
                        runCtx = struct('shallow', shallowObj, 'roiGrid', ctx.roiGrid, 'params', ctx.roiGrid);
                        roiGrid.process(runCtx);
                        close(d);
                        uialert(app.DetecDivUIFigure, 'ROI generation is complete.', 'Success', 'Icon', 'success');
                    catch ME
                        close(d);
                        uialert(app.DetecDivUIFigure, ME.message, 'ROI generation failed', 'Icon', 'error');
                        return;
                    end

                    RefreshtreewindowMenuSelected(app, event);

                case 4
                    trackedParams = app.getProjectDefaultPipelineNodeParams(shallowObj, 'roiTracked');
                    if isempty(trackedParams) || ~isstruct(trackedParams) || isempty(fieldnames(trackedParams))
                        trackedParams = roiTracked.setparam(struct());
                    end
                    choice = uiconfirm(app.DetecDivUIFigure, ...
                        ['This uses tracked masks already present in ROI data to generate moving ROIs.' newline ...
                         'Run createTrackedCellROIs on the current project using default settings?'], ...
                        'Tracked ROIs', ...
                        'Options', {'Run','Cancel'}, ...
                        'DefaultOption', 'Run', ...
                        'CancelOption', 'Cancel');
                    if ~strcmp(choice, 'Run')
                        return;
                    end
                    d = uiprogressdlg(app.DetecDivUIFigure, ...
                        'Title', 'Please Wait...', ...
                        'Message', 'Creating tracked ROIs from masks...');
                    try
                        createTrackedCellROIs(shallowObj);
                        app.applyProjectRoiProducerChoice(shallowObj, 'roiTracked', trackedParams);
                        app.setProjectDefaultPipelineRoiMode(shallowObj, 'tracked');
                        close(d);
                        uialert(app.DetecDivUIFigure, 'Tracked ROI creation is complete.', 'Success', 'Icon', 'success');
                    catch ME
                        close(d);
                        uialert(app.DetecDivUIFigure, ME.message, 'Tracked ROI creation failed', 'Icon', 'error');
                        return;
                    end
                    RefreshtreewindowMenuSelected(app, event);
            end
        end

        % Button pushed function: ExtractROIhypervolumesButton
        function ExtractROIhypervolumesButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end
            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i = app.Tree.SelectedNodes.UserData;
            proj = app.Data.Project{i};
            shallowObj = evalin('base', proj);

            if numel(shallowObj.fov)==1 && numel(shallowObj.fov(1).srcpath)==0
                uialert(app.DetecDivUIFigure,'There is no position available for ROI extraction.','Error');
                return;
            end

            app.ensureProjectDefaultPipelineNode(shallowObj, 'roiExtract');
            params = app.getProjectDefaultPipelineNodeParams(shallowObj, 'roiExtract');
            ctx = struct('shallow', shallowObj);
            if ~isempty(fieldnames(params))
                ctx.roiExtract = params;
                ctx.params = params;
            end
            ctx = roiExtract.ui(ctx);
            if isfield(ctx,'cancelled') && ctx.cancelled
                return;
            end
            if ~isfield(ctx,'roiExtract') || ~isstruct(ctx.roiExtract)
                return;
            end

            app.updateProjectDefaultPipelineNodeParams(shallowObj, 'roiExtract', ctx.roiExtract);

            if ~isfield(ctx,'runNow') || ~ctx.runNow
                RefreshtreewindowMenuSelected(app, event);
                return;
            end

            d = uiprogressdlg(app.DetecDivUIFigure, ...
                'Title', 'Please Wait...', ...
                'Message', 'Extracting ROI hypervolumes...');
            try
                runCtx = struct('shallow', shallowObj, 'roiExtract', ctx.roiExtract, 'params', ctx.roiExtract);
                roiExtract.process(runCtx);
                close(d);
                uialert(app.DetecDivUIFigure,'ROI extraction is complete!','Success','Icon','success');
                RefreshtreewindowMenuSelected(app, event);
            catch ME
                close(d);
                uialert(app.DetecDivUIFigure, ME.message, 'ROI extraction failed', 'Icon', 'error');
                return;
            end
        end

        % Callback function
        function OpendatatosetROIsORtodefineanimagepatternButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            t=app.ProjectsNode.Children(i);
            expand(t);

            uialert(app.DetecDivUIFigure,'Browse data available in the tree menu, right-click Open on the desired dataset,','Warning')
        end

        % Button pushed function: ClassifydataButton
        function ClassifydataButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);
            uialert(app.DetecDivUIFigure, ...
                ['Project-level classification now goes through pipeline templates.' newline ...
                 'Open the project pipeline, adjust the run parameters, then run it from pipeline2.'], ...
                'Use pipelines', 'Icon', 'info');
        end

        % Callback function
        function SelectROItoviewDropDownValueChanged(app, event)
            value = app.SelectROItoviewDropDown.Value;

            % selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
        end

        % Menu selected function: RefreshtreewindowMenu
        function RefreshtreewindowMenuSelected(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Callback function
        function CreateSingleFullScreenROIinallpositionsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase all exisiting ROIs; Proceed?','Warning');


            if strcmp(selection,'OK')

                for i=1:numel(shallowObj.fov)
                    tmp=shallowObj.fov(i).readImage(1,1);
                    roival=[1 1 size(tmp,2) size(tmp,1)];
                    shallowObj.fov(i).roi=roi;
                    shallowObj.fov(i).addROI(roival,shallowObj.fov(i).id)

                end

            end


        end

        % Button pushed function: AddprocessorButton
        function AddprocessorButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure, ...
                    ['Project-level processors are now expected to be configured inside a pipeline template.' newline ...
                     'Create or open the project pipeline and add the processor there.'], ...
                    'Use pipelines', 'Icon', 'info');
                return;
            end
        end

        % Button pushed function: ProcessdataButton
        function ProcessdataButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select an item in the tree window!','Error');
                return;
            end

            tag = app.Tree.SelectedNodes.Tag;

            if strcmp(tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};
                shallowObj=evalin('base',proj);
                app.openProjectPipelineTemplate(shallowObj);
                return;
            end

            if strcmp(tag,'Pipeline')
                idx = app.Tree.SelectedNodes.UserData;
                if idx <= numel(app.Data.Pipeline)
                    pipeVar = app.Data.Pipeline{idx};
                    pipeObj = evalin('base', pipeVar);
                    pipelineRunGUI(pipeObj);
                end
                return;
            end

            if strcmp(tag,'ProjectpipelineRun')
                OpenButtonPushed(app, event);
                return;
            end

            uialert(app.DetecDivUIFigure,'The selected node cannot be processed with this button.','Error');
        end

        % Menu selected function: ExportprojecttoPhylocellMenu
        function ExportprojecttoPhylocellMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            export2phylocellGUI(shallowObj);
        end

        % Callback function
        function CreateFullFrameSingleROIMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            prompt = {'Positions in which ROIs will be created:'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Creating a single full frame ROI';

            dims = [1 100];


            definput = {deffov};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                return;
            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase all exisiting ROIs; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(answer{1});
                if numel(id)
                    for i=id
                        tmp=shallowObj.fov(i).readImage(1,1);
                        roival=[1 1 size(tmp,2) size(tmp,1)];
                        shallowObj.fov(i).roi=roi;
                        shallowObj.fov(i).addROI(roival,shallowObj.fov(i).id)

                    end
                end

                uialert(app.DetecDivUIFigure,'ROI data must now be extracted to be functional...','Warning');
                RefreshtreewindowMenuSelected(app)
            end
        end

        % Callback function
        function DeleteallROIsinallpositionsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return
            end


            selection=uiconfirm(app.DetecDivUIFigure,'This will erase all exisiting ROIs; Proceed?','Warning');

            if strcmp(selection,'OK')
                for i=1:numel(shallowObj.fov)
                    shallowObj.fov(i).roi=roi;
                end
            end


            RefreshtreewindowMenuSelected(app)
        end

        % Callback function
        function DeletePositionsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            prompt = {'Positions that will be deleted:'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Deleting positions';

            dims = [1 100];


            definput = {deffov};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                return;
            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase selected positions; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(answer{1});
                if numel(id)
                    pix=setxor(1:numel(shallowObj.fov),id);
                    shallowObj.fov=shallowObj.fov(pix);
                end

                RefreshtreewindowMenuSelected(app)
            end
        end

        % Callback function
        function SetPositionsorientationMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return
            end


            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            prompt = {'Positions that will be deleted:', 'Orientation in degrees (0,90,180,270):'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Set position orientation';

            dims = [1 100];


            definput = {deffov,'0'};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                return;
            end


            if numel(intersect(str2num(answer{2}),[0 90 180 270]))==0
                uialert(app.DetecDivUIFigure,'Orientation value is wrong; quitting !','Warning');
                return
            end

            id=str2num(answer{1});
            if numel(id)
                for i=1:numel(shallowObj.fov)
                    shallowObj.fov(i).orientation=str2num(answer{2});
                end
            end
        end

        % Callback function
        function AdjustROIssizeMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            defroi=num2str(shallowObj.fov(1).roi(1).value(3:4));
            prompt = {'Positions in which to change ROI size:','New Width/Height [width height]:'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Adjusting ROI size';

            dims = [1 100];


            definput = {deffov,defroi};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                disp('User canceled');
                return;
            end

            sz=str2num(answer{2});

            for i=1:numel(str2num(answer{1}))
                for j=1:numel(1:numel(shallowObj.fov(i).roi))
                    shallowObj.fov(i).roi(j).adjustROISize([0 0 sz(1) sz(2)]);
                end
            end
        end

        % Menu selected function: UserpreferencesMenu
        function UserpreferencesMenuSelected(app, event)
            preferencesGUI;
        end

        % Menu selected function: ClassifierRepositoryMenu
        function ClassifierRepositoryMenuSelected(app, event)
            classiRepository;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)

    d = uiprogressdlg(app.DetecDivUIFigure, ...
        'Title', 'Please wait', ...
        'Message', 'Opening selection...', ...
        'Indeterminate', 'on');

    try
        selectedNodes = app.Tree.SelectedNodes;
        if isempty(selectedNodes) || ~isobject(selectedNodes)
            try, close(d); catch, end
            uialert(app.DetecDivUIFigure, 'Select one item in the tree first.', 'Open');
            return;
        end
        if numel(selectedNodes) > 1
            selectedNodes = selectedNodes(1);
        end
        if ~isprop(selectedNodes, 'UserData') || ~isprop(selectedNodes, 'Tag')
            try, close(d); catch, end
            uialert(app.DetecDivUIFigure, 'The selected tree item cannot be opened.', 'Open');
            return;
        end

        arg = selectedNodes.UserData;
        str = selectedNodes.Tag;
        cc  = arg(1);

        if strcmp(str,'Classifier')
            d.Message = 'Opening classifier...';
            clas = app.Data.Classifier{cc};
            clas = evalin('base', clas);
            classifierGUI(clas);
        end

        if strcmp(str,'Projectclassi')
            d.Message = 'Opening project classifier...';
            proj = app.Data.Project{cc(1)};
            pos  = arg(2);
            shallowObj = evalin('base', proj);
            clas = shallowObj.processing.classification(pos);
            classifierGUI(clas);
        end

        if strcmp(str,'Projectpos')
            d.Message = 'Loading image data...';

            cc  = arg(1);
            pos = arg(2);

            proj = app.Data.Project{cc(1)};
            shallowObj = evalin('base', proj);

            if numel(shallowObj.fov(pos).srcpath{1}) > 0
                im = readImage(shallowObj.fov(pos),1,1);

                if isempty(im)
                    close(d);
                    errordlg('The path to your raw data is incorrect; please update the raw data path !');
                    return
                end
            end

            d.Message = 'Opening field of view...';
            shallowObj.fov(pos).view( ...
                shallowObj.fov(pos).display.frame, []);
        end

        if strcmp(str,'Projectprocess')
            d.Message = 'Opening processing pipeline...';

            proj = app.Data.Project{cc(1)};
            pos  = arg(2);
            shallowObj = evalin('base', proj);
            proc = shallowObj.processing.processor(pos);

            processDataGUI(shallowObj, proc);
        end

        if strcmp(str,'Pipeline')
            jsonPath = app.resolvePipelineJsonPathForNode(selectedNodes);
            [isLoaded, pipeObj] = app.getLoadedPipelineForNode(selectedNodes);
            if ~isLoaded
                d.Message = 'Loading pipeline in workspace...';
                [loaded, pipeObj, ~, jsonPath, msg] = app.loadPipelineForNode(selectedNodes);
                if ~loaded
                    error(msg);
                end
            end

            d.Message = 'Opening pipeline editor...';
            drawnow limitrate nocallbacks;
            app.openPipelineWithContext(pipeObj, d);
            try
                gatherVarsFromWorkspace(app);
                displayNodes(app);
                app.selectPipelineNodeByJsonPath(jsonPath);
            catch
            end
            return;
        end

        if strcmp(str,'PipelineRecent')
            d.Message = 'Loading recent pipeline...';
            pipelineJson = char(string(arg));
            try
                close(d);
            catch
            end
            app.openRecentPipelineCallback(pipelineJson);
            return;
        end

        if strcmp(str,'PipelineModule')
            d.Message = 'Opening pipeline module...';
            pipeIdx = arg(1);
            modIdx = arg(2);
            app.openPipelineModuleByIndex(pipeIdx, modIdx);
        end

        if strcmp(str,'ClassifierpipelineRun')
            app.safeCloseProgressDialog(d);
            app.openClassifierPipelineRunSeed(arg(1), arg(2));
            return;
        end

        if strcmp(str,'ProjectpipelineRun')
            d.Message = 'Preparing pipeline run editor...';
            proj = app.Data.Project{cc(1)};
            runIdx = arg(2);
            shallowObj = evalin('base', proj);
            if ~isfield(shallowObj.processing,'pipelineRun') || runIdx > numel(shallowObj.processing.pipelineRun)
                error('Run not found in project.');
            end

            runObj = shallowObj.processing.pipelineRun(runIdx);
            d.Message = 'Opening pipeline run editor...';
            drawnow;
            app.safeCloseProgressDialog(d);
            app.openPipelineEditorWithProgress(shallowObj, runObj, 'Opening pipeline run editor...');
            return;

            try
                close(d);
            catch
            end
            d = app.createPipelineRunProgressDialog(runObj.runId);
            cancelTokenFile = app.startActivePipelineCancelMonitor(d, runObj.runId);

            pipeObj = [];
            try
                spec = runObj.ctx.pipelineSpec;
                if isstruct(spec) && isfield(spec,'nodes') && ~isempty(spec.nodes)
                    pipeObj = spec;
                    if ~isfield(pipeObj,'edges') || isempty(pipeObj.edges)
                        pipeObj.edges = struct([]);
                    end
                end
            catch
            end
            if isempty(pipeObj) && isprop(runObj,'pipelineRef') && isstruct(runObj.pipelineRef) && isfield(runObj.pipelineRef,'path') ...
                    && ~isempty(runObj.pipelineRef.path)
                [pipeObj, ~] = pipelineLoad(runObj.pipelineRef.path);
            end
            if isempty(pipeObj) && isprop(runObj,'templatePath') && ~isempty(runObj.templatePath)
                [pipeObj, ~] = pipelineLoad(runObj.templatePath);
            end
            if isempty(pipeObj)
                try
                    [foundDefault, defaultPipe] = app.getProjectDefaultPipelineObject(shallowObj);
                    if foundDefault
                        wantId = '';
                        try
                            wantId = char(string(runObj.pipelineRef.id));
                        catch
                        end
                        if isempty(wantId) || strcmp(defaultPipe.strid, wantId)
                            pipeObj = defaultPipe;
                        end
                    end
                catch
                end
            end
            if isempty(pipeObj)
                error('Could not resolve pipeline template for this run.');
            end

            resolvedPath = '';
            try
                if isa(pipeObj,'pipeline') && isprop(pipeObj,'path') && ~isempty(pipeObj.path)
                    resolvedPath = char(string(pipeObj.path));
                end
            catch
            end
            if isempty(resolvedPath)
                try
                    defaultJson = app.getProjectDefaultPipelinePath(shallowObj);
                    if ~isempty(defaultJson)
                        resolvedPath = fileparts(defaultJson);
                    end
                catch
                end
            end
            if ~isempty(resolvedPath)
                if ~isprop(runObj,'pipelineRef') || isempty(runObj.pipelineRef) || ~isstruct(runObj.pipelineRef)
                    runObj.pipelineRef = struct('id','','path','','version','');
                end
                runObj.pipelineRef.path = resolvedPath;
                if isprop(runObj,'templatePath')
                    runObj.templatePath = resolvedPath;
                end
                if isstruct(runObj.ctx)
                    if ~isfield(runObj.ctx,'pipelineRef') || ~isstruct(runObj.ctx.pipelineRef)
                        runObj.ctx.pipelineRef = runObj.pipelineRef;
                    else
                        runObj.ctx.pipelineRef.path = resolvedPath;
                    end
                end
                shallowObj.processing.pipelineRun(runIdx) = runObj;
            end

            try
                runObj.status = 'running';
                shallowObj.processing.pipelineRun(runIdx) = runObj;
                assignin('base', proj, shallowObj);
                pipelineRunSave(runObj);

                ctx = runObj.ctx;
                ctx.shallow = shallowObj;
                ctx.shallowObj = shallowObj;
                ctx.allowGUI = true;
                ctx.cancel = struct('tokenFile', cancelTokenFile);

                [ctxOut, report] = app.runStructuredPipeline(pipeObj, ctx);

                runObj.ctx = ctxOut;
                runObj.outputs = struct('report', report);
                runObj.status = 'done';
                runObj.updatedAt = char(datetime('now'));
                pipelineRunSave(runObj);
                app.syncProjectAfterPipelineRun(proj, ctxOut, runObj, runIdx);

                RefreshtreewindowMenuSelected(app);
            catch MErun
                isCancelled = strcmp(MErun.identifier, 'runPipeline:Cancelled') ...
                    || strcmp(MErun.identifier, 'classifyData:Cancelled') ...
                    || contains(lower(MErun.message), 'cancelled by user');
                if isCancelled
                    runObj.status = 'cancelled';
                else
                    runObj.status = 'failed';
                end
                runObj.updatedAt = char(datetime('now'));
                runObj.outputs = struct('error', struct( ...
                    'message', MErun.message, ...
                    'identifier', MErun.identifier));
                try
                    lastReport = getappdata(0, 'DetecDivLastPipelineReport');
                    if ~isempty(lastReport)
                        runObj.outputs.report = lastReport;
                    end
                catch
                end
                try
                    pipelineRunSave(runObj);
                catch
                end
                try
                    app.syncProjectAfterPipelineRun(proj, shallowObj, runObj, runIdx);
                catch
                end
                RefreshtreewindowMenuSelected(app);
                if isCancelled
                    app.stopActivePipelineCancelMonitor(true);
                    return;
                end
                rethrow(MErun)
            end
            app.stopActivePipelineCancelMonitor(true);
        end

    catch ME
        app.stopActivePipelineCancelMonitor(true);
        try
            close(d);
        catch
        end
        rethrow(ME)
    end

    app.stopActivePipelineCancelMonitor(true);
    try
        close(d);
    catch
    end

        end

        function [ctxOut, report] = runStructuredPipeline(app, pipeObj, ctx) %#ok<INUSD>
            [ctxOut, report] = runPipelineDetecDiv(pipeObj, ctx);
        end

        function syncProjectAfterPipelineRun(app, projectVarName, projectSource, runObj, runIdx)
            projectObj = [];
            try
                projectObj = evalin('base', projectVarName);
            catch
            end
            if isempty(projectObj) || ~isa(projectObj, 'shallow')
                projectObj = app.extractProjectObjectFromRunSource(projectSource);
            end
            if isempty(projectObj)
                return;
            end

            runObj = app.sanitizeRunForPersistence(runObj);

            try
                if isfield(projectObj.processing, 'pipelineRun') && runIdx >= 1 && runIdx <= numel(projectObj.processing.pipelineRun)
                    projectObj.processing.pipelineRun(runIdx) = runObj;
                end
            catch
            end

            projectObj = app.sanitizeProjectForPersistence(projectObj);

            try
                assignin('base', projectVarName, projectObj);
            catch
            end

            savedOk = false;
            projectMatPath = app.getProjectMatPathFromObject(projectObj);
            if ~isempty(projectMatPath)
                try
                    app.saveShallowProjectWithProgress(projectObj, 'Saving project after pipeline run...', 'shallowObj');
                    savedOk = true;
                catch
                end
            end

            if savedOk && ~isempty(projectMatPath) && exist(projectMatPath, 'file') == 2
                try
                    reloadedObj = app.loadProjectMatRaw(projectMatPath);
                    if isa(reloadedObj, 'shallow')
                        assignin('base', projectVarName, reloadedObj);
                    end
                catch
                end
            end
        end

        function runObj = sanitizeRunForPersistence(app, runObj) %#ok<INUSD>
            if isempty(runObj) || ~isa(runObj, 'pipelineRun')
                return;
            end
            try
                runObj.ctx = app.sanitizeCtxForPersistence(runObj.ctx);
            catch
            end
            try
                runObj.outputs = app.sanitizeCtxForPersistence(runObj.outputs);
            catch
            end
            try
                runObj.progress = app.sanitizeCtxForPersistence(runObj.progress);
            catch
            end
        end

        function projectObj = sanitizeProjectForPersistence(app, projectObj)
            if isempty(projectObj) || ~isa(projectObj, 'shallow')
                return;
            end
            try
                if isfield(projectObj.processing, 'pipelineRun') && ~isempty(projectObj.processing.pipelineRun)
                    for ii = 1:numel(projectObj.processing.pipelineRun)
                        projectObj.processing.pipelineRun(ii) = app.sanitizeRunForPersistence(projectObj.processing.pipelineRun(ii));
                    end
                end
            catch
            end
        end

        function S = sanitizeCtxForPersistence(app, S) %#ok<INUSD>
            if ~isstruct(S)
                return;
            end
            fn = fieldnames(S);
            for ii = 1:numel(S)
                for jj = 1:numel(fn)
                    key = fn{jj};
                    try
                        val = S(ii).(key);
                    catch
                        continue;
                    end
                    if isstruct(val)
                        S(ii).(key) = app.sanitizeCtxForPersistence(val);
                    elseif iscell(val)
                        S(ii).(key) = cellfun(@(c) app.sanitizeCellValueForPersistence(c), val, 'UniformOutput', false);
                    else
                        try
                            if isa(val, 'matlab.lang.OnOffSwitchState') || isa(val, 'string') || ischar(val) || isnumeric(val) || islogical(val) || isdatetime(val)
                                continue;
                            end
                            if isa(val, 'handle')
                                S(ii).(key) = struct('className', class(val), 'note', 'omitted for MAT persistence');
                            end
                        catch
                        end
                    end
                end
            end
            try
                if isfield(S, 'exec') && isstruct(S.exec) && isfield(S.exec, 'python') && isstruct(S.exec.python)
                    py = S.exec.python;
                    if isfield(py, 'info')
                        py = rmfield(py, 'info');
                    end
                    if isfield(py, 'pyenv')
                        py = rmfield(py, 'pyenv');
                    end
                    S.exec.python = py;
                end
            catch
            end
        end

        function value = sanitizeCellValueForPersistence(app, value)
            if isstruct(value)
                value = app.sanitizeCtxForPersistence(value);
                return;
            end
            try
                if isa(value, 'handle')
                    value = struct('className', class(value), 'note', 'omitted for MAT persistence');
                end
            catch
            end
        end

        function projectObj = loadProjectMatRaw(app, projectMatPath) %#ok<INUSD>
            projectObj = [];
            if isempty(projectMatPath) || exist(projectMatPath, 'file') ~= 2
                return;
            end
            S = load(projectMatPath, 'shallowObj');
            if isfield(S, 'shallowObj') && isa(S.shallowObj, 'shallow')
                projectObj = S.shallowObj;
                try
                    [pathstr, namestr] = fileparts(projectMatPath);
                    if isunix || ismac
                        projectObj.setPath([pathstr '/'], namestr);
                    else
                        projectObj.setPath([pathstr '\'], namestr);
                    end
                catch
                end
            end
        end

        function projectObj = extractProjectObjectFromRunSource(app, source) %#ok<INUSD>
            projectObj = [];
            try
                if isa(source, 'shallow')
                    projectObj = source;
                    return;
                end
            catch
            end
            if ~isstruct(source)
                return;
            end
            try
                if isfield(source, 'shallow') && isa(source.shallow, 'shallow') && ~isempty(source.shallow)
                    projectObj = source.shallow;
                    return;
                end
            catch
            end
            try
                if isfield(source, 'shallowObj') && isa(source.shallowObj, 'shallow') && ~isempty(source.shallowObj)
                    projectObj = source.shallowObj;
                    return;
                end
            catch
            end
        end

        function projectMatPath = getProjectMatPathFromObject(app, projectObj) %#ok<INUSD>
            projectMatPath = '';
            try
                if isempty(projectObj) || ~isa(projectObj, 'shallow')
                    return;
                end
                if isempty(projectObj.io.path) || isempty(projectObj.io.file)
                    return;
                end
                projectMatPath = fullfile(char(string(projectObj.io.path)), [char(string(projectObj.io.file)) '.mat']);
            catch
                projectMatPath = '';
            end
        end

        function d = createPipelineRunProgressDialog(app, runId)
            if nargin < 2 || isempty(runId)
                runId = 'pipeline_run';
            end
            d = uiprogressdlg(app.DetecDivUIFigure, ...
                'Title', 'Please wait', ...
                'Message', ['Running pipeline run ' char(string(runId)) '...'], ...
                'Indeterminate', 'on', ...
                'Cancelable', 'on');
        end

        function tokenFile = startActivePipelineCancelMonitor(app, d, runId)
            app.stopActivePipelineCancelMonitor(true);

            if nargin < 3 || isempty(runId)
                runId = char(datetime('now','Format','yyyyMMdd_HHmmss_SSS'));
            else
                runId = char(string(runId));
            end
            tokenFile = [tempname(tempdir) '_' matlab.lang.makeValidName(runId) '.cancel'];
            app.ActivePipelineCancelFile = string(tokenFile);
            app.ActivePipelineProgressDialog = d;

            t = timer( ...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 0.25, ...
                'BusyMode', 'drop', ...
                'TimerFcn', @(~,~) app.pollActivePipelineCancelRequest());
            app.ActivePipelineCancelTimer = t;
            start(t);
        end

        function pollActivePipelineCancelRequest(app)
            try
                d = app.ActivePipelineProgressDialog;
                if isempty(d) || ~isvalid(d)
                    return;
                end
                if d.CancelRequested
                    app.requestActivePipelineCancellation();
                end
            catch
            end
        end

        function requestActivePipelineCancellation(app)
            tokenFile = char(app.ActivePipelineCancelFile);
            if isempty(tokenFile)
                return;
            end

            try
                if exist(tokenFile, 'file') ~= 2
                    fid = fopen(tokenFile, 'w');
                    if fid ~= -1
                        fprintf(fid, 'cancelled_at=%s\n', char(datetime('now')));
                        fclose(fid);
                    end
                end
            catch
            end

            try
                d = app.ActivePipelineProgressDialog;
                if ~isempty(d) && isvalid(d)
                    d.Message = 'Cancellation requested... stopping MATLAB/Python work.';
                end
            catch
            end

            try
                pe = pyenv;
                if pe.Status == "Loaded"
                    terminate(pyenv);
                end
            catch
            end
        end

        function stopActivePipelineCancelMonitor(app, deleteToken)
            if nargin < 2
                deleteToken = false;
            end

            try
                t = app.ActivePipelineCancelTimer;
                if ~isempty(t) && isvalid(t)
                    stop(t);
                    delete(t);
                end
            catch
            end
            app.ActivePipelineCancelTimer = [];

            try
                d = app.ActivePipelineProgressDialog;
                if ~isempty(d) && isvalid(d)
                    close(d);
                end
            catch
            end
            app.ActivePipelineProgressDialog = [];

            if deleteToken
                try
                    tokenFile = char(app.ActivePipelineCancelFile);
                    if ~isempty(tokenFile) && exist(tokenFile, 'file') == 2
                        delete(tokenFile);
                    end
                catch
                end
            end
            app.ActivePipelineCancelFile = "";
        end

        % Button pushed function: InspectRunButton
        function InspectRunButtonPushed(app, event)
            if numel(app.Tree.SelectedNodes)==0
                return;
            end
            if strcmp(app.Tree.SelectedNodes.Tag,'ClassifierpipelineRun')
                arg = app.Tree.SelectedNodes.UserData;
                app.openClassifierPipelineRunInspector(arg(1), arg(2));
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'ProjectpipelineRun')
                return;
            end
            arg = app.Tree.SelectedNodes.UserData;
            projIdx = arg(1);
            runIdx = arg(2);

            [runObj, shallowObj] = app.getProjectPipelineRun(projIdx, runIdx);
            if ~isempty(runObj)
                try
                    pipelineRunInspector(runObj, shallowObj);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Pipeline run review error', 'Icon', 'warning');
                end
            end
        end

        % Button pushed function: EditRunButton
        function EditRunButtonPushed(app, event) %#ok<INUSD>
            if numel(app.Tree.SelectedNodes)==0
                return;
            end
            if strcmp(app.Tree.SelectedNodes.Tag,'ClassifierpipelineRun')
                arg = app.Tree.SelectedNodes.UserData;
                app.openClassifierPipelineRunEditor(arg(1), arg(2));
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'ProjectpipelineRun')
                return;
            end
            arg = app.Tree.SelectedNodes.UserData;
            projIdx = arg(1);
            runIdx = arg(2);

            [runObj, shallowObj] = app.getProjectPipelineRun(projIdx, runIdx);
            if ~isempty(runObj)
                try
                    app.openPipelineEditorWithProgress(shallowObj, runObj, 'Opening run for editing...', 'EditExistingRun', true);
                    RefreshtreewindowMenuSelected(app, []);
                catch ME
                    uialert(app.DetecDivUIFigure, ME.message, 'Pipeline run editor error', 'Icon', 'warning');
                end
            end
        end

        function openPipelineRunLog(app, projIdx, runIdx)
            try
                [runObj, shallowObj] = getProjectPipelineRun(app, projIdx, runIdx);
                if isempty(runObj)
                    return;
                end
                [runPath, ~] = runObj.getPath;
                logFile = fullfile(runPath, 'run_log.txt');
                if exist(logFile, 'file') ~= 2
                    pipelineRunSave(runObj);
                    try
                        shallowObj.processing.pipelineRun(runIdx) = runObj;
                    catch
                    end
                end
                showPipelineRunTextFile(app, runObj, logFile, 'Pipeline run log');
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Open run log', 'Icon', 'error');
            end
        end

        function openPipelineRunFolder(app, projIdx, runIdx)
            try
                runObj = getProjectPipelineRun(app, projIdx, runIdx);
                if isempty(runObj)
                    return;
                end
                [runPath, ~] = runObj.getPath;
                if isempty(runPath) || exist(runPath, 'dir') ~= 7
                    uialert(app.DetecDivUIFigure, 'Run folder does not exist yet.', 'Open run folder', 'Icon', 'warning');
                    return;
                end
                openPathInSystem(app, runPath);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Open run folder', 'Icon', 'error');
            end
        end

        function [runObj, shallowObj] = getProjectPipelineRun(app, projIdx, runIdx)
            runObj = [];
            shallowObj = [];
            if projIdx > numel(app.Data.Project)
                return;
            end
            projVar = app.Data.Project{projIdx};
            shallowObj = evalin('base', projVar);
            if ~isfield(shallowObj.processing,'pipelineRun') || runIdx > numel(shallowObj.processing.pipelineRun)
                return;
            end
            runObj = shallowObj.processing.pipelineRun(runIdx);
        end

        function [runObj, clas] = getClassifierPipelineRun(app, classifierIdx, runIdx)
            runObj = [];
            clas = [];
            if classifierIdx > numel(app.Data.Classifier)
                return;
            end
            try
                clas = evalin('base', app.Data.Classifier{classifierIdx});
            catch
                clas = [];
            end
            runPath = '';
            try
                if isfield(app.Data,'ClassifierpipelineRunPath') && classifierIdx <= numel(app.Data.ClassifierpipelineRunPath) && ...
                        runIdx <= numel(app.Data.ClassifierpipelineRunPath{classifierIdx})
                    runPath = char(string(app.Data.ClassifierpipelineRunPath{classifierIdx}{runIdx}));
                end
            catch
                runPath = '';
            end
            if isempty(runPath) || exist(runPath, 'dir') ~= 7
                return;
            end
            try
                [runObj, ~] = pipelineRunLoad(runPath);
            catch
                runObj = [];
            end
        end

        function openClassifierPipelineRunInspector(app, classifierIdx, runIdx)
            [runObj, clas] = app.getClassifierPipelineRun(classifierIdx, runIdx);
            if isempty(runObj)
                return;
            end
            try
                pipelineRunInspector(runObj, clas);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Pipeline run review error', 'Icon', 'warning');
            end
        end

        function openClassifierPipelineRunEditor(app, classifierIdx, runIdx)
            [runObj, ~] = app.getClassifierPipelineRun(classifierIdx, runIdx);
            if isempty(runObj)
                return;
            end
            try
                app.openPipelineEditorWithProgress([], runObj, 'Opening classifier run for editing...', 'EditExistingRun', true);
                RefreshtreewindowMenuSelected(app, []);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Pipeline run editor error', 'Icon', 'warning');
            end
        end

        function openClassifierPipelineRunSeed(app, classifierIdx, runIdx)
            [runObj, ~] = app.getClassifierPipelineRun(classifierIdx, runIdx);
            if isempty(runObj)
                return;
            end
            try
                app.openPipelineEditorWithProgress([], runObj, 'Preparing a new run from classifier run...');
                RefreshtreewindowMenuSelected(app, []);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'New pipeline run error', 'Icon', 'warning');
            end
        end

        function openClassifierPipelineRunLog(app, classifierIdx, runIdx)
            try
                runObj = app.getClassifierPipelineRun(classifierIdx, runIdx);
                if isempty(runObj)
                    return;
                end
                [runPath, ~] = runObj.getPath;
                logFile = fullfile(runPath, 'run_log.txt');
                if exist(logFile, 'file') ~= 2
                    pipelineRunSave(runObj);
                end
                showPipelineRunTextFile(app, runObj, logFile, 'Pipeline run log');
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Open run log', 'Icon', 'error');
            end
        end

        function openClassifierPipelineRunFolder(app, classifierIdx, runIdx)
            try
                runObj = app.getClassifierPipelineRun(classifierIdx, runIdx);
                if isempty(runObj)
                    return;
                end
                [runPath, ~] = runObj.getPath;
                if isempty(runPath) || exist(runPath, 'dir') ~= 7
                    uialert(app.DetecDivUIFigure, 'Run folder does not exist yet.', 'Open run folder', 'Icon', 'warning');
                    return;
                end
                openPathInSystem(app, runPath);
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Open run folder', 'Icon', 'error');
            end
        end

        function showPipelineRunTextFile(app, runObj, filePath, titleText)
            if nargin < 4 || isempty(titleText)
                titleText = 'Pipeline run file';
            end
            [runPath, ~] = runObj.getPath;
            lines = {'File not found.'};
            if exist(filePath, 'file') == 2
                try
                    rawText = fileread(filePath);
                    lines = regexp(rawText, '\r\n|\n|\r', 'split')';
                catch ME
                    lines = {['Unable to read file: ' ME.message]};
                end
            end

            fig = uifigure('Name', titleText, 'Position', [180 120 920 620]);
            grid = uigridlayout(fig, [3 4]);
            grid.RowHeight = {24, '1x', 32};
            grid.ColumnWidth = {'1x', 120, 120, 120};
            grid.Padding = [12 12 12 12];
            grid.RowSpacing = 8;
            grid.ColumnSpacing = 8;

            label = uilabel(grid, 'Text', filePath, 'Interpreter', 'none');
            label.Layout.Row = 1;
            label.Layout.Column = [1 4];

            area = uitextarea(grid, 'Editable', 'off', 'Value', lines);
            area.Layout.Row = 2;
            area.Layout.Column = [1 4];

            folderLabel = uilabel(grid, 'Text', runPath, 'Interpreter', 'none', 'FontColor', [0.35 0.35 0.35]);
            folderLabel.Layout.Row = 3;
            folderLabel.Layout.Column = 1;

            btnFolder = uibutton(grid, 'push', 'Text', 'Open folder', ...
                'ButtonPushedFcn', @(~,~)openPathInSystem(app, runPath));
            btnFolder.Layout.Row = 3;
            btnFolder.Layout.Column = 2;
            btnFile = uibutton(grid, 'push', 'Text', 'Open file', ...
                'ButtonPushedFcn', @(~,~)openPathInSystem(app, filePath));
            btnFile.Layout.Row = 3;
            btnFile.Layout.Column = 3;
            btnClose = uibutton(grid, 'push', 'Text', 'Close', ...
                'ButtonPushedFcn', @(~,~)delete(fig));
            btnClose.Layout.Row = 3;
            btnClose.Layout.Column = 4;
        end

        function openPathInSystem(app, targetPath) %#ok<INUSD>
            targetPath = char(string(targetPath));
            try
                if ispc
                    winopen(targetPath);
                elseif ismac
                    system(['open "' strrep(targetPath, '"', '\"') '" &']);
                else
                    system(['xdg-open "' strrep(targetPath, '"', '\"') '" &']);
                end
            catch ME
                uialert(app.DetecDivUIFigure, ME.message, 'Open path', 'Icon', 'error');
            end
        end

        % Menu selected function: AddclassifierMenu
        function AddclassifierMenuSelected(app, event)
            AddclassifierButtonPushed(app, event)
        end

        % Menu selected function: SetFrameOrientationMenu
        function SetFrameOrientationMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return
            end


            deffov=['1:'  num2str(numel(shallowObj.fov)) ];

            results=myDialog({'Positions','Orientation'},{deffov,{'0','90','180','270','0'}},'Tip',{'Choose the list of positions to be processed','Orientation in deegrees'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose orientation of positions');

            if numel(results)==0
                return;
            end


            id=str2num(results.Positions);
            if numel(id)
                for i=1:numel(shallowObj.fov)
                    shallowObj.fov(i).orientation=str2num(results.Orientation{end});
                end
            end
        end

        % Menu selected function: CreateFullFrameROisMenu
        function CreateFullFrameROisMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];

            results=myDialog({'Positions','Number_of_ROIs_per_frame'},{deffov,{'1' '4' '9' '16' '25' '1'}},'Tip',{'Choose the list of positions to be processed','Choose how many ROIs to split the full frame into'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose parameters to create  ROIs that cover the whole frame ');

            if numel(results)==0
                return;
            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase all exisiting ROIs; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(results.Positions);
                nb=str2num(results.Number_of_ROIs_per_frame{end});

                if numel(id)
                    for j=id
                        tmp=shallowObj.fov(j).readImage(1,1);
                        %    roival=[1 1 size(tmp,2) size(tmp,1)];

                        rows = sqrt(nb);
                        cols = sqrt(nb);
                        k=nb;
                        N=size(tmp,1);
                        M=size(tmp,2);

                        % Calculate the size of each square
                        squareSizeRows = N / rows;
                        squareSizeCols = M / cols;

                        % Initialize cell array to store coordinates
                        coordinates = cell(k, 2);

                        shallowObj.fov(j).roi=roi;

                        for i = 1:k
                            % Calculate the row and column indices of the i-th square
                            rowIdx = ceil(i / cols);
                            colIdx = mod(i - 1, cols) + 1;

                            % Calculate the coordinates of the top-left corner
                            topLeftRow = (rowIdx - 1) * squareSizeRows + 1;
                            topLeftCol = (colIdx - 1) * squareSizeCols + 1;

                            % Calculate the coordinates of the bottom-right corner
                            bottomRightRow = min(rowIdx * squareSizeRows, N);
                            bottomRightCol = min(colIdx * squareSizeCols, M);

                            % Store the coordinates in the cell array
                            %  coordinates{i, 1} = [topLeftRow, topLeftCol];
                            %coordinates{i, 2} = [bottomRightRow, bottomRightCol];

                            roival= [topLeftCol, topLeftRow, bottomRightCol-topLeftCol+1,  bottomRightRow-topLeftRow+1];
                            roival=uint16(roival);
                            shallowObj.fov(j).addROI(roival,shallowObj.fov(j).id)
                        end

                    end
                end

                uialert(app.DetecDivUIFigure,'ROI data must now be extracted to be functional...','Warning','Icon','warning');
                RefreshtreewindowMenuSelected(app)
            end
        end

        % Menu selected function: DeleteROIsMenu
        function DeleteROIsMenuSelected(app, event)

    % 1. Vérifier qu'un projet est sélectionné
    if numel(app.Tree.SelectedNodes) == 0
        uialert(app.DetecDivUIFigure, ...
            'First select a project in the tree window!', ...
            'Error');
        return;
    end

    if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
        uialert(app.DetecDivUIFigure, ...
            'The selected node is not a project!', ...
            'Error');
        return;
    end

    % 2. Charger le projet (shallowObj) depuis base
    projIdx     = app.Tree.SelectedNodes.UserData;
    projVarName = app.Data.Project{projIdx};
    shallowObj  = evalin('base', projVarName);

    % Vérifier qu'il y a bien des FOVs utilisables
    if numel(shallowObj.fov) == 1 && numel(shallowObj.fov.srcpath) == 0
        uialert(app.DetecDivUIFigure, ...
            'There is no position available', ...
            'Warning');
        return;
    end

    nFOV = numel(shallowObj.fov);

    % Pour chaque FOV, on va :
    % - déterminer le nom affiché
    % - déterminer le default ROI deletion string "1:N"
    % - marquer la ligne comme sélectionnée par défaut (true)
    tableSelect   = false(nFOV,1);
    tableName     = cell(nFOV,1);
    tableROIQuery = cell(nFOV,1);

    for ff = 1:nFOV

        % 2a. Nom FOV pour l'affichage
        % On essaie de trouver quelque chose d'informatif
        fovLabel = sprintf('FOV #%d', ff);
        if isprop(shallowObj.fov(ff),'name') && ~isempty(shallowObj.fov(ff).name)
            fovLabel = sprintf('FOV #%d (%s)', ff, shallowObj.fov(ff).name);
        elseif isprop(shallowObj.fov(ff),'id') && ~isempty(shallowObj.fov(ff).id)
            fovLabel = sprintf('FOV #%d (%s)', ff, shallowObj.fov(ff).id);
        elseif isprop(shallowObj.fov(ff),'srcpath') && ~isempty(shallowObj.fov(ff).srcpath)
            [~,tmpn,~] = fileparts(shallowObj.fov(ff).srcpath);
            fovLabel = sprintf('FOV #%d (%s)', ff, tmpn);
        end
        tableName{ff} = fovLabel;

        % 2b. String par défaut des ROIs à supprimer : "1:N"
          if isprop(shallowObj.fov(ff),'roi') && ~isempty(shallowObj.fov(ff).roi)
        nROI = numel(shallowObj.fov(ff).roi);
        if nROI > 0
            if nROI == 1
                tableROIQuery{ff} = '1';
            else
                tableROIQuery{ff} = sprintf('1:%d', nROI);
            end
        else
            tableROIQuery{ff} = '';
        end
    else
        tableROIQuery{ff} = '';
    end

    % 2c. Sélection par défaut : vrai si le champ roi existe
    tableSelect(ff) = ~isempty(tableROIQuery{ff}) && ~strcmp(tableROIQuery{ff},'');
    end

    % 3. Construire la fenêtre modale
    dlg = uifigure('Name','Delete ROIs from Project', ...
                   'WindowStyle','modal', ...
                   'Position',[100 100 600 400]);

    % Layout global
    gl = uigridlayout(dlg, [3 1]);
    gl.RowHeight = {'1x',40,40};
    gl.ColumnWidth = {'1x'};

    % 3a. Table
    t = uitable(gl);
    t.Layout.Row = 1;
    t.Layout.Column = 1;

    % Data de base pour la table
    tData = [num2cell(tableSelect), tableName, tableROIQuery];

    % Définition des colonnes
    % Col1: checkbox editable logique
    % Col2: texte non éditable
    % Col3: texte éditable
    t.ColumnName = {'Select','FOV','ROIs to delete'};
    t.ColumnEditable = [true false true];
    t.Data = tData;

    % Forcer des largeurs raisonnables
    t.ColumnWidth = {60, 220, '1x'};

    % 3b. Ligne boutons Select/Deselect
    rowButtons = uigridlayout(gl,[1 3]);
    rowButtons.Layout.Row = 2;
    rowButtons.Layout.Column = 1;
    rowButtons.ColumnWidth = {100,120,'1x'};

    btnSelectAll = uibutton(rowButtons,'push', ...
        'Text','Select all', ...
        'ButtonPushedFcn',@(~,~) doSelectAll(true));

    btnDeselectAll = uibutton(rowButtons,'push', ...
        'Text','Deselect all', ...
        'ButtonPushedFcn',@(~,~) doSelectAll(false));

    % filler panel pour pousser à gauche
    uilabel(rowButtons,'Text',''); % just spacer

    % 3c. Ligne boutons OK / Cancel
    rowOK = uigridlayout(gl,[1 3]);
    rowOK.Layout.Row = 3;
    rowOK.Layout.Column = 1;
    rowOK.ColumnWidth = {'1x',80,80};

    % spacer
    uilabel(rowOK,'Text','');

    btnCancel = uibutton(rowOK,'push', ...
        'Text','Cancel', ...
        'ButtonPushedFcn',@(~,~) onCancel());

    btnOK = uibutton(rowOK,'push', ...
        'Text','OK', ...
        'ButtonPushedFcn',@(~,~) onOK());

    % On va utiliser uiwait/uiresume pour bloquer tant que la boîte est ouverte
    userChoice = struct('action','cancel','table',[]);
    uiwait(dlg);  % bloquant jusqu'à uiresume dans onOK/onCancel

    % À ce stade, soit OK, soit Cancel a défini userChoice
    if ~isvalid(dlg)
        % La figure a déjà été détruite dans le callback.
        % On lit userChoice.action via le nested scope (fermé dessus).
    end

    % Si cancel -> on sort sans rien changer
    if strcmp(userChoice.action,'cancel')
        return;
    end

    % 4. Application des suppressions ROI selon userChoice.table
    % userChoice.table(:,1) : logical select?
    % userChoice.table(:,3) : string "1:10" etc
    finalData = userChoice.table;

    for ff = 1:nFOV

        % skip si pas sélectionné
        if ~finalData{ff,1}
            continue;
        end

        if ~isprop(shallowObj.fov(ff),'roi') || isempty(shallowObj.fov(ff).roi)
            continue;
        end

        roiStr = strtrim(finalData{ff,3});
        if isempty(roiStr)
            % rien à supprimer
            continue;
        end

        % Parser la chaîne "1:32" etc en indices
        % On utilise eval de façon contrôlée: si l'utilisateur tape une
        % horreur, on catch et on ignore.
        try
            delIdx = eval(['[', roiStr, ']']); %#ok<EVLDIR>
            if ~isnumeric(delIdx)
                delIdx = [];
            end
        catch
            delIdx = [];
        end

        if isempty(delIdx)
            continue;
        end

        allIdx  = 1:numel(shallowObj.fov(ff).roi);
        keepIdx = setxor(allIdx, delIdx);

        if isempty(keepIdx)
            % plus aucune ROI
            shallowObj.fov(ff).roi = roi;
        else
            shallowObj.fov(ff).roi = shallowObj.fov(ff).roi(keepIdx);
        end
    end

    % 5. Sauvegarde de l'objet modifié dans le workspace
    assignin('base', projVarName, shallowObj);

    % 6. Refresh UI
    RefreshtreewindowMenuSelected(app);


    % ==========================
    %  NESTED CALLBACKS
    % ==========================
    function doSelectAll(state)
        % state=true -> tout à true
        % state=false -> tout à false
        tbl = t.Data;
        for r = 1:size(tbl,1)
            tbl{r,1} = state;
        end
        t.Data = tbl;
    end

    function onCancel()
        % L'utilisateur annule : rien à faire
        userChoice.action = 'cancel';
        userChoice.table  = t.Data;
        if isvalid(dlg)
            uiresume(dlg);
            delete(dlg);
        end
    end

    function onOK()
        % Vérifier qu'au moins une ligne est sélectionnée et qu'il y a des ROIs ?
        % (on ne rend pas ça bloquant pour ne pas le frustrer)
        userChoice.action = 'ok';
        userChoice.table  = t.Data;
        if isvalid(dlg)
            uiresume(dlg);
            delete(dlg);
        end
    end


        end

        % Menu selected function: AdjustROIsMenu
        function AdjustROIsMenuSelected(app, event)

            ROIAdjustGUI();
            
            % if numel(app.Tree.SelectedNodes)==0
            %     uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
            %     return;
            % end
            % 
            % if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
            %     uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
            %     return;
            % end
            % 
            % i=app.Tree.SelectedNodes.UserData;
            % % store=app.Tree.SelectedNodes;
            % proj=app.Data.Project{i};
            % shallowObj=evalin('base',proj);
            % 
            % 
            % deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            % defroi=num2str(shallowObj.fov(1).roi(1).value(3:4));
            % results=myDialog({'Positions','ROI_size'},{deffov,defroi},'Tip',{'Choose the list of positions to be processed','Enter new ROI width and height, eg.: 60 60'},...
            %     'CallingApp',app.DetecDivUIFigure,...
            %     'Title','Choose positions in which to adjust ROI size');
            % 
            % if numel(results)==0
            %     return;
            % end
            % 
            % 
            % 
            % sz=str2num(results.ROI_size);
            % 
            % for i=str2num(results.Positions)
            %     for j=1:numel(1:numel(shallowObj.fov(i).roi))
            %         shallowObj.fov(i).roi(j).adjustROISize([0 0 sz(1) sz(2)]);
            %     end
            % end
        end

        % Menu selected function: DeletePositionsMenu_2
        function DeletePositionsMenu_2Selected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end

            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            results=myDialog({'Positions'},{deffov},'Tip',{'Choose the list of positions to delete'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose positions to delete');

            if numel(results)==0
                return;
            end


            selection=uiconfirm(app.DetecDivUIFigure,'This will erase selected positions; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(results.Positions);
                if numel(id)
                    pix=setxor(1:numel(shallowObj.fov),id);
                    shallowObj.fov=shallowObj.fov(pix);
                end

                RefreshtreewindowMenuSelected(app)
            end
        end

        % Menu selected function: AddPositionsDataMenu
        function AddPositionsDataMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end
            i=app.Tree.SelectedNodes.UserData;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            nBefore = app.getProjectFovCount(shallowObj);

            addDataGUI(shallowObj,app);
            uiwait(app.DetecDivUIFigure);

            nAfter = app.getProjectFovCount(shallowObj);
            if nAfter > nBefore
                app.ensureDefaultPipelineForProject(shallowObj);
            end

            TreeSelectionChanged(app, event)
            gatherVarsFromWorkspace(app);
            displayNodes(app);
        end

        % Callback function
        function CleanupROItrainingandresultsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);

            if numel(shallowObj.fov)==1 & numel(shallowObj.fov.srcpath)==0

                uialert(app.DetecDivUIFigure,'There is no position available','Warning');
                return

            end


            deffov=['1:'  num2str(numel(shallowObj.fov)) ];

            % listof training and results field.

            trainstr={};
            resultsstr={};
            cc=1;
            cc2=1;

            for i=1:numel(shallowObj.fov)
                for k=1:numel(shallowObj.fov(i).roi)
                    roiobj=shallowObj.fov(i).roi(k);

                    roiobj.load('results');

                    if numel(roiobj.train)

                        f=fieldnames(roiobj.train);

                        for j=1:numel(f)
                            trainstr{cc}=f{j};
                            cc=cc+1;
                        end
                    end
                    if numel(roiobj.results)
                        f=fieldnames(roiobj.results);

                        for j=1:numel(f)
                            resultsstr{cc2}=f{j};
                            cc2=cc2+1;
                        end
                    end
                end
            end

            trainstr=unique(trainstr);
            resultsstr=unique(resultsstr);

            % if numel(trainstr)==0
            trainstr=[trainstr '-' '-'];
            % end

            % if numel(resultsstr)==0
            resultsstr=[resultsstr '-' '-'];
            %  end

            results=myDialog({'Positions','Train_Fields','Results_Fields','Remove_All_Train','Remove_All_Results'},...
                {deffov,trainstr,resultsstr,false,false},...
                'Tip',{'Choose the positions to process','Choose the training field to remove','Choose the result field to remove','Check to remove all trainings','Check to remove all results'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose fields to remove from training and results');

            if numel(results)==0
                return;
            end


            for i=str2num(results.Positions)
                for k=1:numel(shallowObj.fov(i).roi)

                    roiobj=shallowObj.fov(i).roi(k);

                    if numel(roiobj.image)==0
                        roiobj.load;
                    end

                    if results.Remove_All_Train==1
                        roiobj.train=[];
                    else
                        tr=results.Train_Fields{end};
                        if ~strcmp(tr,'-')
                            roiobj.removeData({tr},{});
                        end
                    end
                    if results.Remove_All_Results==1
                        roiobj.results=[];
                    else
                        roiobj.load('results');
                        re=results.Results_Fields{end};
                        if ~strcmp(re,'-')
                            roiobj.removeData({},{re});
                        end
                    end

                    roiobj.save('results');
                end
            end


            RefreshtreewindowMenuSelected(app)


        end

        % Callback function
        function CleanupROIstrainingandresultsMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            selectedNodes=  app.Tree.SelectedNodes;

            okclas=0;
            if strcmp(selectedNodes.Tag,'Projectclassi')
                okclas=1;
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
            end

            if strcmp(selectedNodes.Tag,'Classifier')
                okclas=1;
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{cc};

                clas=evalin('base',proj);
            end

            if okclas==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            % listof training and results field.

            trainstr={};
            resultsstr={};
            cc=1;
            cc2=1;
            for k=1:numel(clas.roi)
                roiobj=clas.roi(k);

                roiobj.load('results');

                if numel(roiobj.train)

                    f=fieldnames(roiobj.train);

                    for j=1:numel(f)
                        trainstr{cc}=f{j};
                        cc=cc+1;
                    end
                end
                if numel(roiobj.results)
                    f=fieldnames(roiobj.results);

                    for j=1:numel(f)
                        resultsstr{cc2}=f{j};
                        cc2=cc2+1;

                    end
                end
            end

            trainstr=unique(trainstr);
            resultsstr=unique(resultsstr);

            % if numel(trainstr)==0
            trainstr=[trainstr '-' '-'];
            % end

            % if numel(resultsstr)==0
            resultsstr=[resultsstr '-' '-'];
            %  end

            results=myDialog({'Train_Fields','Results_Fields','Remove_All_Train','Remove_All_Results'},...
                {trainstr,resultsstr,false,false},...
                'Tip',{'Choose the training field to remove','Choose the result field to remove','Check to remove all trainings but the one selected above','Check to remove all results but the one selected above'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Choose fields to remove from training and results');

            if numel(results)==0
                return;
            end


            for k=1:numel(clas.roi)

                roiobj=clas.roi(k);
                if numel(roiobj.image)==0
                    roiobj.load;
                end

                if results.Remove_All_Train==1
                    tr=results.Train_Fields{end};
                    if ~strcmp(tr,'-')
                        tmp=setxor(trainstr,tr);
                        roiobj.removeData(tmp,{});
                    else
                        roiobj.train=[];
                    end
                else
                    tr=results.Train_Fields{end};
                    if ~strcmp(tr,'-')
                        roiobj.removeData({tr},{});
                    end
                end

                if results.Remove_All_Results==1
                    tr=results.Results_Fields{end};
                    if ~strcmp(tr,'-')
                        tmp=setxor(resultsstr,tr);
                        roiobj.removeData({},tmp);
                    else

                        roiobj.results=[];
                    end
                else
                    roiobj.load('results');
                    re=results.Results_Fields{end};
                    if ~strcmp(re,'-')
                        roiobj.removeData({},{re});
                    end
                end

                roiobj.save;

            end


            RefreshtreewindowMenuSelected(app)

        end

        % Callback function
        function ChangeChannelnameMenuSelected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
                return;
            end

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
                uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
                return;
            end

            i=app.Tree.SelectedNodes.UserData;
            % store=app.Tree.SelectedNodes;
            proj=app.Data.Project{i};
            shallowObj=evalin('base',proj);


            deffov=['1:'  num2str(numel(shallowObj.fov)) ];
            listChannels=listAvailableChannels;
            listChannels{end+1}=listChannels{1};
            newname='newchannel';

            results=myDialog({'Positions','ChannelToReplace','NewChannelName'},{deffov,listChannels,newname},...
                'Tip',{'Choose the list of positions to be processed','Choose the channel to be replaced','Enter the new name'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Change the name of a channel');

            if numel(results)==0
                return;
            end


            chan=results.ChannelToReplace{end};


            newchan=results.NewChannelName;

            for i=str2num(results.Positions)

                disp([ 'Processing position ' num2str(i) 'out of ' results.Positions]);
                for j=1:numel(1:numel(shallowObj.fov(i).roi))

                    pix=shallowObj.fov(i).roi(j).findChannelID(chan);

                    if numel(pix)
                        shallowObj.fov(i).roi(j).display.channel{pix}=newchan;
                        disp('Change channel name is done !')
                    else
                        disp(['Channel ' chan ' not found for ROI ' shallowObj.fov(i).roi(j).id ]);
                    end
                end
            end


        end

        % Callback function
        function ChangechannelnameMenuSelected(app, event)

            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            selectedNodes=app.Tree.SelectedNodes;

            okclas=0;
            if strcmp(selectedNodes.Tag,'Projectclassi')
                okclas=1;
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{cc(1)};
                pos=cc(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
            end

            if strcmp(selectedNodes.Tag,'Classifier')
                okclas=1;
                cc=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Classifier{cc};

                clas=evalin('base',proj);
            end

            if okclas==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            defroi=['1:'  num2str(numel(clas.roi)) ];

            listChannels=listAvailableChannels
            listChannels{end+1}=listChannels{1};
            newname='newchannel';

            results=myDialog({'ROIs','ChannelToReplace','NewChannelName'},{defroi,listChannels,newname},...
                'Tip',{'Choose the ROIs to replace','Choose the channel to be replaced','Enter the new name'},...
                'CallingApp',app.DetecDivUIFigure,...
                'Title','Change the name of a channel');

            if numel(results)==0
                return;
            end


            chan=results.ChannelToReplace{end};


            newchan=results.NewChannelName;

            rois=str2num(results.ROIs);

            for j=rois %1:numel(1:numel(clas.roi))

                pix=clas.roi(j).findChannelID(chan);

                if numel(pix)
                    clas.roi(j).display.channel{pix}=newchan;
                    disp(['Change channel name is done for roi ' clas.roi(j).id '!'])
                else
                    disp(['Channel ' chan ' not found for ROI ' clas.roi(j).id ]);
                end
            end


        end

        % Menu selected function: DeleteROIsMenu_2
        function DeleteROIsMenu_2Selected(app, event)
            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            selectedNodes=app.Tree.SelectedNodes;
            str=app.Tree.SelectedNodes.Tag;

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Classifier') && ~strcmp(app.Tree.SelectedNodes.Tag,'Projectclassi')
                uialert(app.DetecDivUIFigure,'The selected node is not a classifier !','Error');
                return;
            end

            arg=selectedNodes.UserData;
            cc=arg(1);

            if strcmp(str,'Classifier')
                clas=app.Data.Classifier{cc};
                clas=evalin('base',clas);
            end
            if strcmp(str,'Projectclassi')
                proj=app.Data.Project{cc(1)};
                pos=arg(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
            end


            defroi=['1:'  num2str(numel(clas.roi)) ];
            prompt = {'ROIs that will be deleted:'};%,'Period between frames for each channel (in frames units):'};
            dlgtitle = 'Deleting ROIs';

            dims = [1 100];


            definput = {defroi};%, num2str(inte)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if numel(answer)==0
                return;
            end

            selection=uiconfirm(app.DetecDivUIFigure,'This will erase selected ROIs; Proceed?','Warning');


            if strcmp(selection,'OK')

                id=str2num(answer{1});
                if numel(id)
                    pix=setxor(1:numel(clas.roi),id);

                    if numel(pix)==0
                        clas.roi=roi;
                        clas.channelName={};
                    else
                        clas.roi=clas.roi(pix);
                    end

                end

                RefreshtreewindowMenuSelected(app)
            end


        end

        % Menu selected function: ClassifyDataMenu
        function ClassifyDataMenuSelected(app, event)
            classifyDataGUI;
        end

        % Menu selected function: RestoredeletedROIsMenu
        function RestoredeletedROIsMenuSelected(app, event)
           
% SNIPPET FROM DEV BRANCH (commit ee414e4 context)
% Paste this callback into GUI/detecdiv.mlapp if you want the ROI restore
% logic that merges ROIs from disk (HDF5) into the selected position.

% Restore/Merge ROIs from disk into shallowObj.fov(pix).roi
% Disk ROI files are HDF5 named: im_<id>.h5 (or .hdf5)
% Canonical ROI id = substring between "im_" and ".h5"
% Loading is done via roiobj.load(...) using roiobj.path + roiobj.id

% ---- basic checks ----
if isempty(app.Tree.SelectedNodes)
    uialert(app.DetecDivUIFigure,'First select a project in the tree window!','Error');
    return;
end
if ~strcmp(app.Tree.SelectedNodes.Tag,'Project')
    uialert(app.DetecDivUIFigure,'The selected node is not a project!','Error');
    return;
end

projIdx = app.Tree.SelectedNodes.UserData;
projVar = app.Data.Project{projIdx};   % base workspace var name
shallowObj = evalin('base', projVar);

% ---- position list ----
posList = arrayfun(@(f) string(f.id), shallowObj.fov, 'UniformOutput', true);
posList = posList(posList ~= "");
if isempty(posList)
    uialert(app.DetecDivUIFigure,'No positions found in project (shallowObj.fov is empty).','Error');
    return;
end

% myDialog expects: { items..., defaultValue }
posDialog = cellstr(posList);
posDialog{end+1} = posDialog{1};

results = myDialog({'Position_to_restore'}, {posDialog}, ...
    'Tip',{'Choose the position in which the ROIs should be restored / merged'}, ...
    'CallingApp',app.DetecDivUIFigure, ...
    'Title','Restore ROIs (merge with disk)');

if isempty(results), return; end
posname = string(results.Position_to_restore{end});
pix = find(strcmp(cellstr(posList), posname), 1);
if isempty(pix)
    uialert(app.DetecDivUIFigure,'Selected position not found in project.','Error');
    return;
end

% ---- disk folder ----
dirpath = fullfile(shallowObj.io.path, shallowObj.io.file, char(posList(pix)));
if ~isfolder(dirpath)
    uialert(app.DetecDivUIFigure, "Folder not found: " + dirpath, 'Error');
    return;
end

% ---- scan H5 ROI files (im_<id>.h5) ----
diskFiles = [dir(fullfile(dirpath,'im_*.h5')); dir(fullfile(dirpath,'im_*.hdf5'))];
diskFiles = diskFiles(~[diskFiles.isdir]);

if isempty(diskFiles)
    uialert(app.DetecDivUIFigure, "No ROI files found (im_*.h5) in: " + dirpath, 'Info');
    return;
end

% ---- ROIs in memory ----
roiMem = [];
if isfield(shallowObj.fov(pix),'roi') && ~isempty(shallowObj.fov(pix).roi)
    roiMem = shallowObj.fov(pix).roi;
end

% ---- index memory by id ----
memIdToIdx = containers.Map('KeyType','char','ValueType','int32');
if ~isempty(roiMem)
    for i = 1:numel(roiMem)
        rid = "";
        try, rid = string(roiMem(i).id); catch, end
        if rid ~= "" && ~isKey(memIdToIdx, char(rid))
            memIdToIdx(char(rid)) = int32(i);
        end
    end
end

added = 0;
replaced = 0;
unreadable = 0;

for k = 1:numel(diskFiles)
    fname = diskFiles(k).name;
    id = roiIdFromImH5Filename(fname);
    if id == ""
        unreadable = unreadable + 1;
        fprintf('[RESTORE][SKIP] %s -> not im_<id>.h5\n', fname);
        continue;
    end

    h5File = fullfile(dirpath, sprintf('im_%s.h5', char(id)));
    if ~isfile(h5File)
        h5File = fullfile(dirpath, sprintf('im_%s.hdf5', char(id)));
    end
    dataFile = fullfile(dirpath, sprintf('data_%s.mat', char(id))); %#ok<NASGU>

    fprintf('\n[RESTORE] %3d/%3d  id="%s"\n', k, numel(diskFiles), char(id));
    fprintf('          H5  : %s\n', h5File);
    fprintf('          MAT : %s\n', dataFile);

    if ~isfile(h5File)
        unreadable = unreadable + 1;
        fprintf('          -> FAIL: missing H5 file\n');
        continue;
    end

    % HDF5 sanity
    try
        info = h5info(h5File);
        nD = numel(info.Datasets);
        nG = numel(info.Groups);
        fprintf('          h5info OK: %d datasets, %d groups\n', nD, nG);
    catch MEh5
        unreadable = unreadable + 1;
        fprintf('          -> FAIL: H5 unreadable: %s\n', MEh5.message);
        continue;
    end

    % Load via roi.load (debug verbose)
    try
        roiDisk = roi();
        roiDisk.path = dirpath;
        roiDisk.id = char(id);
        roiDisk.load('Debug');

        % merge
        if isKey(memIdToIdx, char(id))
            idx = memIdToIdx(char(id));
            roiMem(idx) = roiDisk;
            replaced = replaced + 1;
            fprintf('          -> OK: replaced existing ROI\n');
        else
            if isempty(roiMem), roiMem = roiDisk;
            else, roiMem(end+1) = roiDisk; %#ok<AGROW>
            end
            memIdToIdx(char(id)) = int32(numel(roiMem));
            added = added + 1;
            fprintf('          -> OK: added ROI\n');
        end
    catch ME
        unreadable = unreadable + 1;
        fprintf('          -> FAIL in roi.load: %s\n', ME.message);
        for s = 1:numel(ME.stack)
            fprintf('             at %s (line %d)\n', ME.stack(s).name, ME.stack(s).line);
        end
    end
end

% ---- write back once ----
shallowObj.fov(pix).roi = roiMem;
assignin('base', projVar, shallowObj);

msg = sprintf(['Merge done for %s\n' ...
               '- Disk files scanned: %d\n' ...
               '- Added: %d\n' ...
               '- Replaced (same id): %d\n' ...
               '- Unreadable/ignored: %d'], ...
               posname, numel(diskFiles), added, replaced, unreadable);
uialert(app.DetecDivUIFigure, msg, 'Restore ROIs');

% ===== local helper =====
function id = roiIdFromImH5Filename(fnameLocal)
    [~, base, ext] = fileparts(fnameLocal);
    if ~strcmpi(ext,'.h5') && ~strcmpi(ext,'.hdf5')
        id = "";
        return;
    end
    if startsWith(base, 'im_', 'IgnoreCase', false)
        id = string(extractAfter(base, 'im_'));
    else
        id = "";
    end
end


        end

        % Menu selected function: ExportdataMenu
        function ExportdataMenuSelected(app, event)
            %     exportdata;
            detector;
        end

        % Menu selected function: ConvertoldtrainingandresultdataMenu
        function ConvertoldtrainingandresultdataMenuSelected(app, event)

            app.Data.st=gatherVariablesFromWorkspace;

            s=app.Data.st;


            % list avaialable fovs and classi with ROIs


            list=[];

            for i=1:numel(s.Project)

                proj=s.Project{i};

                for k=1:numel(s.Projectpos{i})

                    tmp= evalin('base',[proj '.fov(' num2str(k) ')']);

                    for j=1:numel(tmp.roi)
                        list=[list tmp.roi(j)];
                    end

                end

                for k=1:numel(s.Projectclassi{i})

                    tmp= evalin('base',[proj '.processing.classification(' num2str(k) ')']);

                    for j=1:numel(tmp.roi)
                        list=[list tmp.roi(j)];
                    end


                end
            end
            for i=1:numel(s.Classifier)

                clas=s.Classifier{i};

                tmp= evalin('base',clas);

                for j=1:numel(tmp.roi)
                    list=[list tmp.roi(j)];
                end


            end

            formatInDataSeries(list);

        end

        % Menu selected function: ProcessdataMenu
        function ProcessdataMenuSelected(app, event)
            processDataGUI;
        end

        % Menu selected function: MakeROImoviesMenu
        function MakeROImoviesMenuSelected(app, event)
            movieGUI;
        end

        % Menu selected function: RestoredeletedROIsMenu_2
        function RestoredeletedROIsMenu_2Selected(app, event)
            % in case rois have been redfined, but are still stored on the
            % hard drive, this function allows one to restore the previous
            % verison

            if numel(app.Tree.SelectedNodes)==0
                uialert(app.DetecDivUIFigure,'First select a classifier in the tree window!','Error');
                return;
            end

            selectedNodes=app.Tree.SelectedNodes;
            str=app.Tree.SelectedNodes.Tag;

            if ~strcmp(app.Tree.SelectedNodes.Tag,'Classifier') && ~strcmp(app.Tree.SelectedNodes.Tag,'Projectclassi')
                uialert(app.DetecDivUIFigure,'The selected node is not a classifier !','Error');
                return;
            end

            arg=selectedNodes.UserData;
            cc=arg(1);

            if strcmp(str,'Classifier')
                clas=app.Data.Classifier{cc};
                clas=evalin('base',clas);
            end

            if strcmp(str,'Projectclassi')
                proj=app.Data.Project{cc(1)};
                pos=arg(2);
                shallowObj=evalin('base',proj);
                clas=shallowObj.processing.classification(pos);
            end

            dirpath=fullfile(clas.path);

            ls=dir(dirpath);
            lsname={ls.name};

            for i=1:numel(clas.roi)

                nme=['im_' clas.roi(i).id '.mat'];

                if numel(find(matches(lsname,nme)))
                    fle=fullfile(dirpath,nme);
                    tmp=load(fle);
                    clas.roi(i)=tmp.roiobj;
                    %  clas.roi(i).clear;
                    clas.roi(i).save;
                    clas.roi(i).clear;
                end

            end
        end

        % Menu selected function: DeleteclassifierMenu
        function DeleteclassifierMenuSelected(app, event)

        end

        % Menu selected function: AdjustROIsMenu_2
        function AdjustROIsMenu_2Selected(app, event)
            ROIAdjustGUI();
        end

        % Menu selected function: AdjustROIsMenu_3
        function AdjustROIsMenu_3Selected(app, event)
            ROIAdjustGUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DetecDivUIFigure = uifigure('Visible', 'off');
            app.DetecDivUIFigure.Name = 'DetecDiv';
            app.DetecDivUIFigure.Icon = 'detecDiv_logo.png';
            app.FileMenu = uimenu(app.DetecDivUIFigure);
            app.FileMenu.Text = 'File';
            app.NewprojectMenu = uimenu(app.FileMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Text = 'New project...';
            app.OpenprojectMenu = uimenu(app.FileMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project...';
            app.CatalogBrowserMenu = uimenu(app.FileMenu);
            app.CatalogBrowserMenu.MenuSelectedFcn = createCallbackFcn(app, @CatalogBrowserMenuSelected, true);
            app.CatalogBrowserMenu.Text = 'Catalog browser...';
            app.OpenrecentMenu = uimenu(app.FileMenu);
            app.OpenrecentMenu.Text = 'Open recent';
            app.Item1Menu = uimenu(app.OpenrecentMenu);
            app.Item1Menu.Text = 'Item1';
            app.SaveselectedprojectMenu = uimenu(app.FileMenu);
            app.SaveselectedprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveselectedprojectMenuSelected, true);
            app.SaveselectedprojectMenu.Text = 'Save selected project';
            app.Closeproject = uimenu(app.FileMenu);
            app.Closeproject.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectMenuSelected, true);
            app.Closeproject.Text = 'Close selected project';
            app.ReloadprojectfromdiskMenu = uimenu(app.FileMenu);
            app.ReloadprojectfromdiskMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadprojectfromdiskMenuSelected, true);
            app.ReloadprojectfromdiskMenu.Text = 'Reload selected project from disk';
            app.ExportprojecttoPhylocellMenu = uimenu(app.FileMenu);
            app.ExportprojecttoPhylocellMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportprojecttoPhylocellMenuSelected, true);
            app.ExportprojecttoPhylocellMenu.Separator = 'on';
            app.ExportprojecttoPhylocellMenu.Text = 'Export project to Phylocell...';
            app.NewprojectindependentclassiiferMenu = uimenu(app.FileMenu);
            app.NewprojectindependentclassiiferMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectindependentclassiiferMenuSelected, true);
            app.NewprojectindependentclassiiferMenu.Separator = 'on';
            app.NewprojectindependentclassiiferMenu.Text = 'New project-independent classiifer...';
            app.OpenprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.OpenprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectindependentclassifierMenuSelected, true);
            app.OpenprojectindependentclassifierMenu.Text = 'Open project-independent classifier...';
            app.OpenrecentClassiMenu = uimenu(app.FileMenu);
            app.OpenrecentClassiMenu.Text = 'Open recent independent classifier';
            app.SaveprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.SaveprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveprojectindependentclassifierMenuSelected, true);
            app.SaveprojectindependentclassifierMenu.Text = 'Save project-independent classifier';
            app.CloseprojectindependentclassifierMenu = uimenu(app.FileMenu);
            app.CloseprojectindependentclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectindependentclassifierMenuSelected, true);
            app.CloseprojectindependentclassifierMenu.Text = 'Close project-independent classifier';
            app.ClassifierRepositoryMenu = uimenu(app.FileMenu);
            app.ClassifierRepositoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassifierRepositoryMenuSelected, true);
            app.ClassifierRepositoryMenu.Separator = 'on';
            app.ClassifierRepositoryMenu.Text = 'Classifier Repository...';
            app.PipelineTemplatesMenu = uimenu(app.FileMenu);
            app.PipelineTemplatesMenu.Text = 'Pipeline Templates';
            app.NewpipelinetemplateMenu = uimenu(app.PipelineTemplatesMenu);
            app.NewpipelinetemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @NewpipelinetemplateMenuSelected, true);
            app.NewpipelinetemplateMenu.Text = 'New pipeline template...';
            app.OpenpipelinetemplateMenu = uimenu(app.PipelineTemplatesMenu);
            app.OpenpipelinetemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenpipelinetemplateMenuSelected, true);
            app.OpenpipelinetemplateMenu.Text = 'Open pipeline template...';
            app.OpenrecentPipelineMenu = uimenu(app.PipelineTemplatesMenu);
            app.OpenrecentPipelineMenu.Text = 'Open recent pipeline';
            uimenu(app.PipelineTemplatesMenu, ...
                'Separator', 'on', ...
                'Text', 'Clear recent pipeline list', ...
                'MenuSelectedFcn', @(src,evt)app.clearRecentPipelines());
            app.ClosepipelinetemplateMenu = uimenu(app.PipelineTemplatesMenu);
            app.ClosepipelinetemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosepipelinetemplateMenuSelected, true);
            app.ClosepipelinetemplateMenu.Text = 'Close pipeline template';
            app.ConvertoldtrainingandresultdataMenu = uimenu(app.FileMenu);
            app.ConvertoldtrainingandresultdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertoldtrainingandresultdataMenuSelected, true);
            app.ConvertoldtrainingandresultdataMenu.Separator = 'on';
            app.ConvertoldtrainingandresultdataMenu.Text = 'Convert old training and result data';
            app.FunctionsMenu = uimenu(app.DetecDivUIFigure);
            app.FunctionsMenu.Text = 'Functions';
            app.ClassifyDataMenu = uimenu(app.FunctionsMenu);
            app.ClassifyDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassifyDataMenuSelected, true);
            app.ClassifyDataMenu.Text = 'Classify Data...';
            app.ProcessdataMenu = uimenu(app.FunctionsMenu);
            app.ProcessdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessdataMenuSelected, true);
            app.ProcessdataMenu.Text = 'Process data...';
            app.ExportdataMenu = uimenu(app.FunctionsMenu);
            app.ExportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportdataMenuSelected, true);
            app.ExportdataMenu.Text = 'Export data...';
            app.MakeROImoviesMenu = uimenu(app.FunctionsMenu);
            app.MakeROImoviesMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeROImoviesMenuSelected, true);
            app.MakeROImoviesMenu.Text = 'Make ROI movies...';
            app.ProjectMenu = uimenu(app.DetecDivUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.PositionsMenu = uimenu(app.ProjectMenu);
            app.PositionsMenu.Text = 'Positions';
            app.AddPositionsDataMenu = uimenu(app.PositionsMenu);
            app.AddPositionsDataMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPositionsDataMenuSelected, true);
            app.AddPositionsDataMenu.Text = 'Add Positions (Data)...';
            app.SetFrameOrientationMenu = uimenu(app.PositionsMenu);
            app.SetFrameOrientationMenu.MenuSelectedFcn = createCallbackFcn(app, @SetFrameOrientationMenuSelected, true);
            app.SetFrameOrientationMenu.Separator = 'on';
            app.SetFrameOrientationMenu.Text = 'Set Frame Orientation...';
            app.CreateFullFrameROisMenu = uimenu(app.PositionsMenu);
            app.CreateFullFrameROisMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateFullFrameROisMenuSelected, true);
            app.CreateFullFrameROisMenu.Text = 'Create Full Frame ROis...';
            app.DeleteROIsMenu = uimenu(app.PositionsMenu);
            app.DeleteROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteROIsMenuSelected, true);
            app.DeleteROIsMenu.Text = 'Delete ROIs...';
            app.AdjustROIsMenu = uimenu(app.PositionsMenu);
            app.AdjustROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustROIsMenuSelected, true);
            app.AdjustROIsMenu.Text = 'Adjust ROIs...';
            app.DeletePositionsMenu_2 = uimenu(app.PositionsMenu);
            app.DeletePositionsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DeletePositionsMenu_2Selected, true);
            app.DeletePositionsMenu_2.Text = 'Delete Positions...';
            app.RestoredeletedROIsMenu = uimenu(app.PositionsMenu);
            app.RestoredeletedROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoredeletedROIsMenuSelected, true);
            app.RestoredeletedROIsMenu.Text = 'Restore deleted ROIs...';
            app.ForcerawdatapathrebaseMenu = uimenu(app.ProjectMenu);
            app.ForcerawdatapathrebaseMenu.MenuSelectedFcn = createCallbackFcn(app, @ForcerawdatapathrebaseMenuSelected, true);
            app.ForcerawdatapathrebaseMenu.Separator = 'on';
            app.ForcerawdatapathrebaseMenu.Text = 'Force raw-data path rebase...';
            app.ClassifiersMenu = uimenu(app.ProjectMenu);
            app.ClassifiersMenu.Text = 'Classifiers';
            app.AddclassifierMenu = uimenu(app.ClassifiersMenu);
            app.AddclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @AddclassifierMenuSelected, true);
            app.AddclassifierMenu.Text = 'Add classifier...';
            app.DeleteclassifierMenu = uimenu(app.ClassifiersMenu);
            app.DeleteclassifierMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteclassifierMenuSelected, true);
            app.DeleteclassifierMenu.Text = 'Delete classifier...';
            app.AdjustROIsMenu_3 = uimenu(app.ClassifiersMenu);
            app.AdjustROIsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @AdjustROIsMenu_3Selected, true);
            app.AdjustROIsMenu_3.Text = 'Adjust ROIs...';
            app.DeleteROIsMenu_2 = uimenu(app.ClassifiersMenu);
            app.DeleteROIsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DeleteROIsMenu_2Selected, true);
            app.DeleteROIsMenu_2.Text = 'Delete ROIs...';
            app.RestoredeletedROIsMenu_2 = uimenu(app.ClassifiersMenu);
            app.RestoredeletedROIsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RestoredeletedROIsMenu_2Selected, true);
            app.RestoredeletedROIsMenu_2.Text = 'Restore deleted ROIs...';
            app.AdjustROIsMenu_2 = uimenu(app.ProjectMenu);
            app.AdjustROIsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AdjustROIsMenu_2Selected, true);
            app.AdjustROIsMenu_2.Text = 'Adjust ROIs...';
            app.DisplayMenu = uimenu(app.DetecDivUIFigure);
            app.DisplayMenu.Text = 'Display';
            app.RefreshtreewindowMenu = uimenu(app.DisplayMenu);
            app.RefreshtreewindowMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshtreewindowMenuSelected, true);
            app.RefreshtreewindowMenu.Text = 'Refresh tree window';
            app.SettingsMenu = uimenu(app.DetecDivUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.UserpreferencesMenu = uimenu(app.SettingsMenu);
            app.UserpreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @UserpreferencesMenuSelected, true);
            app.UserpreferencesMenu.Text = 'User preferences...';
            app.Tree = uitree(app.DetecDivUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'Use left-click to scroll projects and classifiers; Use right-click to open positions and classifiers'};
            app.ProjectsNode = uitreenode(app.Tree);
            app.ProjectsNode.Text = 'Projects';
            app.IndependentClassifiersNode = uitreenode(app.Tree);
            app.IndependentClassifiersNode.Text = 'Independent Classifiers';
            app.PipelinesNode = uitreenode(app.Tree);
            app.PipelinesNode.Text = 'Pipelines';
            app.ProjectsPanel = uipanel(app.DetecDivUIFigure);
            app.ProjectsPanel.Title = 'Projects';
            app.UIAxes = uiaxes(app.ProjectsPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.AdddataButton = uibutton(app.ProjectsPanel, 'push');
            app.AdddataButton.ButtonPushedFcn = createCallbackFcn(app, @AdddataButtonPushed, true);
            app.AdddataButton.Icon = 'data.png';
            app.AdddataButton.Visible = 'off';
            app.AdddataButton.Tooltip = {'Link directory containing images to the project'};
            app.AdddataButton.Text = 'Add data...';
            app.AddclassifierButton = uibutton(app.ProjectsPanel, 'push');
            app.AddclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @AddclassifierButtonPushed, true);
            app.AddclassifierButton.Icon = 'brain.png';
            app.AddclassifierButton.Visible = 'off';
            app.AddclassifierButton.Tooltip = {'Creates a new classifier or imports an existing classifier to the project'};
            app.AddclassifierButton.Text = 'Add classifier...';
            app.ProjectInformationLabel = uilabel(app.ProjectsPanel);
            app.ProjectInformationLabel.Text = 'Project Information';
            app.IdentifyROIsinpositionsButton = uibutton(app.ProjectsPanel, 'push');
            app.IdentifyROIsinpositionsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyROIsinpositionsButtonPushed, true);
            app.IdentifyROIsinpositionsButton.Visible = 'off';
            app.IdentifyROIsinpositionsButton.Tooltip = {'This allows you to use a pattern to identify multiple ROIs automatically. The pattern must be defined in one of the positions'};
            app.IdentifyROIsinpositionsButton.Text = 'Identify ROIs in positions...';
            app.ExtractROIhypervolumesButton = uibutton(app.ProjectsPanel, 'push');
            app.ExtractROIhypervolumesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractROIhypervolumesButtonPushed, true);
            app.ExtractROIhypervolumesButton.Visible = 'off';
            app.ExtractROIhypervolumesButton.Tooltip = {'Generates the ROIs by extracting subimages from raw data and copy them in the project directory. After this step, access to the raw data is no longer necessary'};
            app.ExtractROIhypervolumesButton.Text = 'Extract ROI hypervolumes...';
            app.UpdaterawdatapathButton = uibutton(app.ProjectsPanel, 'push');
            app.UpdaterawdatapathButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterawdatapathButtonPushed, true);
            app.UpdaterawdatapathButton.Visible = 'off';
            app.UpdaterawdatapathButton.Tooltip = {'Please select the directory that contains  a list of images in your project'};
            app.UpdaterawdatapathButton.Text = 'Update raw data path...';
            app.ClassifydataButton = uibutton(app.ProjectsPanel, 'push');
            app.ClassifydataButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifydataButtonPushed, true);
            app.ClassifydataButton.Icon = 'gears.png';
            app.ClassifydataButton.Visible = 'off';
            app.ClassifydataButton.Tooltip = {'Classifies defined ROIs using a given classifier '};
            app.ClassifydataButton.Text = 'Classify data...';
            app.AddprocessorButton = uibutton(app.ProjectsPanel, 'push');
            app.AddprocessorButton.ButtonPushedFcn = createCallbackFcn(app, @AddprocessorButtonPushed, true);
            app.AddprocessorButton.Icon = 'processor.png';
            app.AddprocessorButton.Visible = 'off';
            app.AddprocessorButton.Text = 'Add processor...';
            app.ProcessdataButton = uibutton(app.ProjectsPanel, 'push');
            app.ProcessdataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessdataButtonPushed, true);
            app.ProcessdataButton.Icon = 'gears.png';
            app.ProcessdataButton.Visible = 'off';
            app.ProcessdataButton.Text = 'Process data...';
            app.InspectRunButton = uibutton(app.ProjectsPanel, 'push');
            app.InspectRunButton.ButtonPushedFcn = createCallbackFcn(app, @InspectRunButtonPushed, true);
            app.InspectRunButton.Visible = 'off';
            app.InspectRunButton.Text = 'Review run';
            app.EditRunButton = uibutton(app.ProjectsPanel, 'push');
            app.EditRunButton.ButtonPushedFcn = createCallbackFcn(app, @EditRunButtonPushed, true);
            app.EditRunButton.Visible = 'off';
            app.EditRunButton.Text = 'Edit run';
            app.OpenButton = uibutton(app.ProjectsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Visible = 'off';
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.DetecDivUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detecdiv

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DetecDivUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DetecDivUIFigure)
                runningApp.applyMainWindowLayout();
                runningApp.resetMainPanelState();
                RefreshtreewindowMenuSelected(runningApp, []);
                drawnow;

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)
            if strlength(app.WorkspaceEventListenerId) > 0
                detecdiv_event('unsubscribe', app.WorkspaceEventListenerId);
                app.WorkspaceEventListenerId = "";
            end
            app.stopActivePipelineCancelMonitor(true);
            app.stopRoiTreeBuild();

            % Delete UIFigure when app is deleted
            delete(app.DetecDivUIFigure)
        end
    end
end