classdef movieGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MoviemakerUIFigure              matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavemovieparametersasMenu       matlab.ui.container.Menu
        LoadmovieparametersMenu         matlab.ui.container.Menu
        SetButton                       matlab.ui.control.Button
        OutputfileEditField             matlab.ui.control.EditField
        OutputfileEditFieldLabel        matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        MakemovieorsequenceButton       matlab.ui.control.Button
        SaveparametersandpreviewButton  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SelectROIsTab                   matlab.ui.container.Tab
        SetdisplayparametersTab         matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        ContoursIndexedchannelsLabel    matlab.ui.control.Label
        GrayscaleandRGBchannelsLabel    matlab.ui.control.Label
        ROIArraysizeEditField           matlab.ui.control.EditField
        ROIArraysizeEditFieldLabel      matlab.ui.control.Label
        OutputImagesizeEditField        matlab.ui.control.EditField
        OutputImagesizeEditFieldLabel   matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        FramesEditField                 matlab.ui.control.EditField
        FramesEditFieldLabel            matlab.ui.control.Label
        SetmovieparametersTab           matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        function param = newMovie(app)
            
            tip={'Select movie, sequence of images, or mat file containing all assembled images',...
                'Set movie title',...
                'Check box to display the name of each ROI',...
                'Set RGB values for movie background',...
                'Set RGB values for text',...
                'Set text font size',...
                'Set scaling factor for output images',...
                'Enter a rectangle in the form of [ l t w h] to crop each ROI',...
                'Check to hide the time stamp',...
                'Offset the starting time of the movie',...
                'Specify display framerate',...
                'Specify image acquisition framerate'};

            param=struct('Output_type',{{'Movie','Sequence','Mat','Movie'}},...
                'Movie_title','Mymovie',...
                'Display_ROI_title',false,...
                'Background_color','0 0 0',...
                'Text_color','1 1 0',...
                'Fontsize',14,...
                'Movie_scale',1,...
                'Movie_crop',[],...
                'Hide_timestamp',false,...
                'Offset_start_time',false,...
                'Frames_per_second',20,...
                'Minutes_per_frame',10,...
                'tip',{tip});
        end
        
        function display2(app,datapicker)
            % param to be included in the display tab:
            %Frames, Channel, Levels, RGB, contour, ArraySize,  ImageSize

            % param to be included in the parameters tab : 
            %% IPS, SnapRate
            
            % stopDead
            % Rotate
            %Flip

            %%HideStamp
            %%FontSize
            %%Title
            %%ROITitle
            %%Output : Sequence, Movie, Mat
            %%Background (backgroud color)
           % %Text (tect color)
            %%Scale 
            %%Crop
         
            % other params : 
            % Name
            %Mosaic (list ROIS)
            % Training, Results
            %Classification
            %RLS
            %Legend (legend for class)

            
            d = uiprogressdlg(app.MoviemakerUIFigure,'Title','Please Wait...',...
                'Message','Loading movie parameters...');
            d.Value=0.33;

            movie=evalin('base','detecmovie');
            param=movie.Param;

            tip=param.tip;
            param=rmfield(param,'tip');

            app.OutputfileEditField.Value=movie.outputFile;

            if numel(app.SetmovieparametersTab.Children)
                delete(app.SetmovieparametersTab.Children)
                app.SetmovieparametersTab.UserData=[];
            end

            struct2GUI(param,[10 330],'Handle',app.SetmovieparametersTab,'Tip',tip,'Flag',app.Lamp);
            param.tip=tip;

            if nargin==2 % creates ROI picker
                if numel(movie.Data.nodeid)
                    %nodes=app.Data.groups(pix).Data;Data
                    nodes=movie.Data.nodeid;
                    app.Data.storedrois=movie.Data.roiobj;
                else
                    nodes=[];
                    app.Data.storedrois=roi;
                end

                if isa('datatree','var')
                    if ishandle(datatree)
                        delete(datatree)
                    end
                end

                d.Value=0.5;

                d.Message='Refreshing data picker...';

                [datatree,~]=datapickerGUI2('Handle',app.SelectROIsTab,'Flag',app.Lamp,'Position',[12 12 540 340],'Input',nodes);
                app.Data.tree=datatree;
                %  tmp=app.Data.tree.UserData
                drawnow
            end

            % create data source picker
            rois=movie.Data.roiobj;

            if numel(rois)==0
                return;
            end

            if isfield(app.Data, 'sourcetree')
                delete(app.Data.sourcetree)
            end

            % Tree = uitree(app.SelectDatasourceTab,'checkbox');
            % Tree.CheckedNodesChangedFcn = {@checkChanged, app.Lamp};
            % Tree.Tooltip = {'Select data to be plotted or exported'};
            % Tree.Position = [10 10 540 310];
            % app.Data.sourcetree=Tree;

            % if numel(roi)
            %     typedata=groups(pix).Type; %Param.Plot_type{end};
            % 
            %     fields=gatherROIsfields(app,rois,typedata);
            % 
            %     if numel(groups(pix).Source)==0
            %         sourceid={};
            %     else
            %         sourceid=groups(pix).Source.nodeid;
            %     end
            % 
            %     checkedNodes=[];
            % 
            %     for i=1:numel(fields.Results{1})
            %         if numel(fields.Results{2}{i})
            %             h3(i)=uitreenode(Tree,'Text',fields.Results{1}{i},'Tag','Results','UserData',[]);
            % 
            %             for j=1:numel(fields.Results{2}{i})
            % 
            %                 str= fields.Results{2}{i}{j}; % fields.Results{3}{i}{j}];
            %                 h4(i,j)=uitreenode(h3(i),'Text',str,'Tag',['Results' num2str(i) '_' num2str(j)],'UserData',...
            %                     {fields.Results{1}{i} fields.Results{2}{i}{j}} );
            %                 if numel(sourceid)
            %                     if numel(find(matches(sourceid,['Results' num2str(i) '_' num2str(j)])))
            %                         checkedNodes=[checkedNodes; h4(i,j)];
            %                     end
            %                 end
            %             end
            %         end
            %     end
            % 
            %     Tree.CheckedNodes=checkedNodes;
            % 
            %     expand(Tree);
            % end

            % update fields in the display tab
            % gather default frame data

            rois2=app.Data.storedrois;
            keeptable=false;
            if size(rois2) == size(rois) & rois2==rois % rois did not change, so we can keep the table display as is; 
                keeptable=true;
            end

            if keeptable==true
                t=app.UITable;
                t.Data=movie.Display.Channel;
                t=app.UITable_2;
                t.Data=movie.Display.IndexedChannel;

               app.FramesEditField.Value=  movie.Display.Frames;
               app.ROIArraysizeEditField.Value= movie.Display.ArraySize;
               app.OutputImagesizeEditField.Value=movie.Display.OutputSize;
            else

                if numel(rois)
                s=[];
                for i=1:numel(rois)
                        s=[s ; rois(i).value(3:4)];
                end
                
                chk=s==s(1,:);
                if ~all(chk,'all')
                    disp('Error : all the ROIs have different sizes')
                    return
                end
                
                app.OutputImagesizeEditField.Value=num2str(s(1,:));
                
                if numel(rois(1).image)==0
                    rois(1).load
                end

                app.FramesEditField.Value=['1:' num2str(size(rois(1).image,4))];

                n=uint16(numel(rois));
                switch n
                    case 1
                         w=1; h=1;
                    case 2
                        w=2; h=1;
                    case 3
                        w=3; h=1; 
                    otherwise
                        w=ceil(sqrt(double(n))); h=floor(sqrt(double(n)));
                end
                app.ROIArraysizeEditField.Value=num2str([h w]);
                end

                % gather channels 
                % HERE 
               % tc=app.UITable.Data;
              %  ti=app.UITable_2.Data;
                tc={};
                ti={};

                ci=1;
                cc=1;

                if numel(rois)
                for i=1:numel(rois(1).display.channel)
                    pix=rois(1).findChannelID(rois(1).display.channel{i});

                     if numel(pix)==1 & sum(rois(1).display.intensity(i,:))==0 % indexed channel
                         tmp=rois(1).image(:,:,pix,:);
                       ma=uint16(max(tmp(:)));

                     %  cmap=lines(ma);
                   %    for k=1:ma
                        ti{ci,1}=false;
                        ti{ci,2}=rois(1).display.channel{i};
                        ti{ci,3}=['1:' num2str(ma)];
                        ti{ci,4}=['jet(' num2str(ma) ')'];
                        ti{ci,5}=1;
                        ti{ci,6}=false;
                        ti{ci,7}=1;
                        ci=ci+1;
                   %    end
                         continue
                     end

                     
                     obj=rois(1);

                             if (~isfield(obj.display,'displaylim') && ~isprop(obj.display,'displaylim')) || numel(obj.display.displaylim)~=2*numel(obj.channelid)
            disp(['No display limits found for ROI ' num2str(obj.id) ', computing them...']);
            
            obj.computeDisplaylim;
                             end
                             

                        tmp=round(65536*rois(1).display.displaylim(:,pix)); tmp=tmp';

                     if numel(pix)==3 % RGB image
                       if cc==1
                        tc{cc,1}=true;
                       else
                        tc{cc,1}=false;
                       end

                        tc{cc,2}=rois(1).display.channel{i};
                        tmp=round(mean(tmp,1));

                        tc{cc,3}=num2str(tmp);
                        tc{cc,4}='1 1 1';
                        tc{cc,5}=false;
                        tc{cc,6}=1;
                        cc=cc+1;
                         continue
                     end

                    % otherwise grayscale channel 
                       if cc==1
                        tc{cc,1}=true;
                       else
                        tc{cc,1}=false;
                       end

                      tc{cc,2}=rois(1).display.channel{i};
                      tc{cc,3}=num2str(tmp);
                      tc{cc,4}='1 1 1';
                      tc{cc,5}=false;
                      tc{cc,6}=1;
                      cc=cc+1;

                % d{i,3}='indexed image';
                % d{i,4}='N/A';
                % 
                % d{i,2}=roiobj.display.channel{i};
                % 
                % d{i,3}='grayscale';
                % tmp=round(65536*roiobj.display.displaylim(:,pix)); tmp=tmp';
                % 
                 if numel(pix)==3
                % d{i,3}='RGB image';
                % tmp=round(mean(tmp,1));
                % end
                % 
                % d{i,4}=num2str(tmp);
                % 
                % if numel(pix)==1 & sum(roiobj.display.intensity(i,:))==0
                % d{i,3}='indexed image';
                % d{i,4}='N/A';
                 end

                end
                end

              app.UITable.Data=tc;
              app.UITable_2.Data=ti;
            end

            % set output directory fields

            d.Value=0.8;
            d.Message='Set output directories...';

            % enable available buttons
            test_roi=false;
            test_source=false;

            if numel(movie.Data.roiobj)
                test_roi=true;
            end

            % 
            % if isfield(groups(sel).Source,'nodeid')
            %     if numel(groups(sel).Source.nodeid)
            %         test_source=true;
            %     end
            % end

            app.MakemovieorsequenceButton.Enable="off";

            if test_roi %&& test_source
                app.MakemovieorsequenceButton.Enable="on";
            end

            %   function checkChanged(src, event, flag)
            % 
            %     if numel(flag)
            %         flag.Color=[1 0 0];
            %     end
            % 
            %     nodeid={};
            %     nodename={};
            % 
            %     checkedN = event.LeafCheckedNodes;
            % 
            %     if numel(checkedN)
            %         for k=1:numel(checkedN)
            %             nodeid{end+1}=checkedN(k).Tag;
            %             nodename{end+1}=checkedN(k).UserData;
            %         end
            %     end
            % 
            %     src.UserData.nodeid=nodeid;
            %     src.UserData.nodename=nodename;
            % end

            app.Data.storedrois=rois; 

            d.Value=0.9;
            d.Message='Data loaded...';
            close(d)
        
        end
        
        function preview(app,displaytest)
            movie=evalin('base','detecmovie');
 dat=app.UITable.Data;
 dat2=app.UITable_2.Data;
 if numel(dat)==0
 return;
 end
 movie.Display.Channel= app.UITable.Data;
 movie.Display.IndexedChannel= app.UITable_2.Data;
 app.Lamp.Color=[0 1 0];
 
 arg={'Frames',str2num(app.FramesEditField.Value)};
 arg=[arg {'Output',movie.Param.Output_type{end}}];
 arg=[arg {'Name',app.OutputfileEditField.Value}];
 arg=[arg {'IPS',movie.Param.Frames_per_second}];
 arg=[arg {'Framerate',movie.Param.Minutes_per_frame}];
 arg=[arg {'HideStamp',movie.Param.Hide_timestamp}];
 arg=[arg {'TimeOffset',movie.Param.Offset_start_time}];
 arg=[arg {'Background',str2num(movie.Param.Background_color)}];
 arg=[arg {'Text',str2num(movie.Param.Text_color)}];
 arg=[arg {'FontSize',movie.Param.Fontsize}];
 if numel(movie.Param.Movie_title)
 arg=[arg {'Title',movie.Param.Movie_title}];
 end
 arg=[arg {'Scale',movie.Param.Movie_scale}];
 arg=[arg {'Crop',movie.Param.Movie_crop}];
 arg=[arg {'ROITitle',movie.Param.Display_ROI_title}];
 arg=[arg {'ArraySize',str2num(app.ROIArraysizeEditField.Value)}];
 if numel(dat)~=0
 selectedchannels=find(cellfun(@(x) x==1,dat(:,1)));
 channels=dat(selectedchannels,2);
 channels=channels';
 %cha= movie.Data.roiobj(1).display.channel(selectedchannels);
 levels=dat(selectedchannels,3);
 levels= cellfun(@(x) str2num(x), levels,'UniformOutput',false);
 levels=levels';
 else
 % selectedchannels=[];
 channels=[];
 level=[];
 selectedchannels=[];
 end
 if numel(dat2)~=0
 selectedchannels2=find(cellfun(@(x) x==1,dat2(:,1)));
 channels2=dat2(selectedchannels2,2);
 channels2=channels2';
 levels2={};
 for i=1:numel(selectedchannels2)
 levels2{i}=dat2(selectedchannels2,3:7);
 end
 else
 channels2=[];
 levels2=[];
 selectedchannels2=[];
 end
 levels=[levels levels2];
 channels=[channels channels2];
 if numel(channels)
 arg=[arg {'Channel',channels'}];
 arg=[arg {'Levels',levels'}];
 end
 colors=[];
 if numel(selectedchannels)
 colors=dat(selectedchannels,4);
 colors= cellfun(@(x) str2num(x), colors,'UniformOutput',false);
 colors=colors';
 end
 colors2=[];
 if numel(selectedchannels2)
 colors2=dat2(selectedchannels2,4);
 colors2=colors2';
 end
 colors=[colors colors2];
 if numel(colors)
 arg=[arg {'RGB',colors'}];
 end
 weights=[];
 if numel(selectedchannels)
 weights=cell2mat(dat(selectedchannels,6));
 end
 weights2=[];
 if numel(selectedchannels2)
 weights2=cell2mat(dat2(selectedchannels2,6));
 end
 weights=[weights ; weights2];
 if numel(weights)
 arg=[arg {'Weights',weights'}];
 end
 if nargin==2
 arg=[arg {'DisplayTest'}];
 end
 mosaic(movie.Data.roiobj,arg{:});
 end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.Data=[];

            varlist=evalin('base','who');
            ok=0;

            for i=1:numel(varlist)
                if strcmp(varlist{i},'detecmovie')
                    % groups=evalin('base','datagroups');
                    ok=1;
                    %  break
                end
            end

            if ok==0
                param = newMovie(app);

                movie=struct('Name','group01','Data',[],'Param',param,'Display',[],'Source',[],'Type','temporal','outputFile',fullfile(userpath,'mymovie.mp4'));
                movie.Data.nodeid=[];
                movie.Data.roiobj=[];
                movie.Source.nodeid={};
                movie.tip=param.tip;
                movie.Display.Frames='';
                movie.Display.ArraySize='1 1';
                movie.Display.OutputSize='';
                movie.Display.Channel={};
                movie.Display.IndexedChannel={};

                %movie.Display.Channel

                assignin('base','detecmovie',movie);
            end


            t=app.UITable;
            t.Data={};
            t.ColumnWidth={50, 'auto', 100, 70, 90, 70};
            t.ColumnEditable=[true false true true true true];

             t=app.UITable_2;
             t.Data={};
             t.ColumnWidth={50, 'auto' , 70, 100, 70, 70,70};
             t.ColumnEditable=[true false true true true true true];

            display2(app,'datapicker')
        end

        % Button pushed function: SaveparametersandpreviewButton
        function SaveparametersandpreviewButtonPushed(app, event)
       
            movie=evalin('base','detecmovie');

            test_roi=false;
       %     test_source=false;

  
                if numel(app.Data.tree.UserData)
                    if numel(app.Data.tree.UserData.nodeid)
                        movie.Data.nodeid= app.Data.tree.UserData.nodeid;
                        movie.Data.roiobj= app.Data.tree.UserData.roiobj;
                        movie.Data.filepath= app.Data.tree.UserData.filepath;
                        movie.Data.type= app.Data.tree.UserData.type;
                    else
                        % groups(sel).Data.nodeid=[];
                        % groups(sel).Data.roiobj= [];
                        % groups(sel).Data.filepath= [];
                        % groups(sel).Data.type= [];
                    end
                end

                if numel(app.SetmovieparametersTab.UserData)
                    movie.Param=app.SetmovieparametersTab.UserData;
                    movie.Param.tip=movie.tip;

                    % adjust export file name 

                    typ=movie.Param.Output_type{end};
                    name=movie.outputFile;
                    [pth fle]=fileparts(name);

                      switch typ
                          case 'Movie'
                                   fil=fullfile(pth, [fle '.mp4']);
                          case 'Sequence'
                                       fil=fullfile(pth, [fle '.png']);
                          case 'Mat'
                                 fil=fullfile(pth, [fle '.mat']);
                      end

                      movie.outputFile=fil;
                      app.OutputImagesizeEditField.Value=fil;  
                end

                 movie.Display.Frames=app.FramesEditField.Value;
                 movie.Display.OutputSize=   app.OutputImagesizeEditField.Value;
                 movie.Display.ArraySize= app.ROIArraysizeEditField.Value;
                 movie.Display.Channel= app.UITable.Data;
                 movie.Display.IndexedChannel= app.UITable_2.Data;


                % if numel(app.Data.sourcetree.UserData)
                %     if numel(app.Data.sourcetree.UserData.nodeid)
                %         groups(sel).Source=app.Data.sourcetree.UserData;
                % 
                %     else
                %         %     groups(sel).Source=[];
                %     end
                % end

                app.Lamp.Color=[0 1 0];
                assignin('base','detecmovie',movie);

                display2(app)
                preview(app,'displaytest')

           
        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            preview(app,'displaytest')
    
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if numel(indices)
                if indices(2)==5
                    movie=evalin('base','detecmovie');
                    t=app.UITable.Data;
                    t{indices(1),5}=false;
                    
                    pix=indices(1);
                    roiobj=movie.Data.roiobj(1);
                    roiobj.computeDisplaylim('Channel',pix);
                    t{indices(1),3}=num2str( [ uint16(65536*roiobj.display.displaylim(1,indices(1)))  uint16(65536*roiobj.display.displaylim(2,indices(1))) ]   );    

                    app.UITable.Data=t;

                end
            end

      
                   %           t.Data{indices(1),5}=false;
                     %         t.Data{indices(1),4}=num2str( [ uint16(65536*roiobj.display.displaylim(1,indices(1)))  uint16(65536*roiobj.display.displaylim(2,indices(1))) ]   ); % HERE
                     %       app.UITable=t;

            
            preview(app,'displaytest')
        end

        % Button pushed function: MakemovieorsequenceButton
        function MakemovieorsequenceButtonPushed(app, event)
            % press button to generate movie 

            preview(app)
        end

        % Value changed function: ROIArraysizeEditField
        function ROIArraysizeEditFieldValueChanged(app, event)
            value = app.ROIArraysizeEditField.Value;
             app.Lamp.Color=[1 0 0];
        end

        % Value changed function: OutputImagesizeEditField
        function OutputImagesizeEditFieldValueChanged(app, event)
            value = app.OutputImagesizeEditField.Value;
             app.Lamp.Color=[1 0 0];
        end

        % Value changed function: FramesEditField
        function FramesEditFieldValueChanged(app, event)
            value = app.FramesEditField.Value;
             app.Lamp.Color=[1 0 0];
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
              tmp=evalin('base','detecmovie');
            [file,path] = uiputfile('*.tif; *.mp4; *.mat' ,'Enter the path and filename of the exported file',tmp.outputFile);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);
   
                tmp.outputFile=filename;
                app.OutputfileEditField.Value=filename;
                assignin('base','detecmovie',tmp);
            end
        end

        % Menu selected function: SavemovieparametersasMenu
        function SavemovieparametersasMenuSelected(app, event)
             [file,path] = uiputfile('*.mat','Choose a movie settings filename',userpath);

            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                 movie=evalin('base','detecmovie');

                save(filename, 'movie');
            end
        end

        % Menu selected function: LoadmovieparametersMenu
        function LoadmovieparametersMenuSelected(app, event)
               [file,path] = uigetfile('*.mat','Select a movie project',userpath);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                d=load(filename);

                % % check if projects are loaded ; if not , load them ...
                % for i=1:numel(groups)
                %     %  tmp=groups(i).Data
                %     for j=1:numel(groups(i).Data.filepath)
                %         if numel(groups(i).Data.filepath{j})
                %             if strcmp(groups(i).Data.type{j},'shallow')
                %                 sha=shallowLoad(groups(i).Data.filepath{j});
                %                 if numel(sha)
                %                     name=sha.io.file;
                %                     assignin('base',name,sha);
                %                 end
                %             end
                %             if strcmp(groups(i).Data.type{j},'classi')
                %                 sha=classiLoad(groups(i).Data.filepath{j});
                %                 if numel(sha)
                %                     name=[sha.strid '_indep'];
                %                     assignin('base',name,sha);
                %                 end
                %             end
                %         end
                %     end
                % end

          %      assignin('base','datagroups',groups);
          %    assignin('base','datagroups_savedir',groups_dir);
           %   assignin('base','datagroups_savesheet',groups_sheet);

           movie=d.movie;
           assignin('base','detecmovie',movie);

             display2(app,'refresh');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MoviemakerUIFigure = uifigure('Visible', 'off');
            app.MoviemakerUIFigure.Name = 'Movie maker';
            app.FileMenu = uimenu(app.MoviemakerUIFigure);
            app.FileMenu.Text = 'File...';
            app.SavemovieparametersasMenu = uimenu(app.FileMenu);
            app.SavemovieparametersasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavemovieparametersasMenuSelected, true);
            app.SavemovieparametersasMenu.Text = 'Save movie parameters as...';
            app.LoadmovieparametersMenu = uimenu(app.FileMenu);
            app.LoadmovieparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadmovieparametersMenuSelected, true);
            app.LoadmovieparametersMenu.Text = 'Load movie parameters...';
            app.TabGroup = uitabgroup(app.MoviemakerUIFigure);
            app.SelectROIsTab = uitab(app.TabGroup);
            app.SelectROIsTab.Title = '1) Select ROIs';
            app.SetdisplayparametersTab = uitab(app.TabGroup);
            app.SetdisplayparametersTab.Title = '2) Set display parameters';
            app.FramesEditFieldLabel = uilabel(app.SetdisplayparametersTab);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.SetdisplayparametersTab, 'text');
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.UITable = uitable(app.SetdisplayparametersTab);
            app.UITable.ColumnName = {'Select'; 'Name'; 'Levels'; 'Color'; 'Auto-adjust'; 'Weight'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.OutputImagesizeEditFieldLabel = uilabel(app.SetdisplayparametersTab);
            app.OutputImagesizeEditFieldLabel.Text = 'Output Image size';
            app.OutputImagesizeEditField = uieditfield(app.SetdisplayparametersTab, 'text');
            app.OutputImagesizeEditField.ValueChangedFcn = createCallbackFcn(app, @OutputImagesizeEditFieldValueChanged, true);
            app.OutputImagesizeEditField.Editable = 'off';
            app.OutputImagesizeEditField.Enable = 'off';
            app.ROIArraysizeEditFieldLabel = uilabel(app.SetdisplayparametersTab);
            app.ROIArraysizeEditFieldLabel.Text = 'ROI Array size:';
            app.ROIArraysizeEditField = uieditfield(app.SetdisplayparametersTab, 'text');
            app.ROIArraysizeEditField.ValueChangedFcn = createCallbackFcn(app, @ROIArraysizeEditFieldValueChanged, true);
            app.GrayscaleandRGBchannelsLabel = uilabel(app.SetdisplayparametersTab);
            app.GrayscaleandRGBchannelsLabel.Text = 'Grayscale and RGB channels';
            app.ContoursIndexedchannelsLabel = uilabel(app.SetdisplayparametersTab);
            app.ContoursIndexedchannelsLabel.Text = 'Contours / Indexed channels';
            app.UITable_2 = uitable(app.SetdisplayparametersTab);
            app.UITable_2.ColumnName = {'Select'; 'Name'; 'Indices'; 'Colormap'; 'Weight'; 'Contour'; 'Width'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.SetmovieparametersTab = uitab(app.TabGroup);
            app.SetmovieparametersTab.Title = '3) Set movie parameters';
            app.SaveparametersandpreviewButton = uibutton(app.MoviemakerUIFigure, 'push');
            app.SaveparametersandpreviewButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersandpreviewButtonPushed, true);
            app.SaveparametersandpreviewButton.Text = 'Save parameters and preview';
            app.MakemovieorsequenceButton = uibutton(app.MoviemakerUIFigure, 'push');
            app.MakemovieorsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @MakemovieorsequenceButtonPushed, true);
            app.MakemovieorsequenceButton.Text = 'Make movie or sequence';
            app.Lamp = uilamp(app.MoviemakerUIFigure);
            app.OutputfileEditFieldLabel = uilabel(app.MoviemakerUIFigure);
            app.OutputfileEditFieldLabel.Text = 'Output file:';
            app.OutputfileEditField = uieditfield(app.MoviemakerUIFigure, 'text');
            app.SetButton = uibutton(app.MoviemakerUIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set...';
            % Show the figure after all components are created
            app.MoviemakerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = movieGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MoviemakerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MoviemakerUIFigure)
        end
    end
end