classdef pipeline2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewpipelineMenu                 matlab.ui.container.Menu
        SavecurrentpipelineMenu         matlab.ui.container.Menu
        SavepipelineasMenu              matlab.ui.container.Menu
        SaverunMenu                     matlab.ui.container.Menu
        SaverunasMenu                   matlab.ui.container.Menu
        ExportpipelineMenu              matlab.ui.container.Menu
        MainTabGroup                    matlab.ui.container.TabGroup
        BuildTab                        matlab.ui.container.Tab
        ModuleParametersPanel           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        RuntimeInputsTab                matlab.ui.container.Tab
        NewRunButton                    matlab.ui.control.Button
        PipelinestatusLabel             matlab.ui.control.Label
        CheckbuildButton                matlab.ui.control.Button
        IdEditField                     matlab.ui.control.EditField
        IdEditFieldLabel                matlab.ui.control.Label
        PipelineandRuncheckreportLabel  matlab.ui.control.Label
        GraphPanel                      matlab.ui.container.Panel
        UIGraphAxes                     matlab.ui.control.UIAxes
        RunTab                          matlab.ui.container.Tab
        HubparametersPanel              matlab.ui.container.Panel
        RuntimestatusLabel              matlab.ui.control.Label
        RuninformationhereLabel         matlab.ui.control.Label
        RunTargetDropDown               matlab.ui.control.DropDown
        RunTargetDropDownLabel          matlab.ui.control.Label
        ResumeoptionsDropDown           matlab.ui.control.DropDown
        ResumeoptionsDropDownLabel      matlab.ui.control.Label
        ExecutionDropDown               matlab.ui.control.DropDown
        ExecutionDropDownLabel          matlab.ui.control.Label
        UISelectedModuleTable           matlab.ui.control.Table
        SelectedmodulesLabel            matlab.ui.control.Label
        RuntimeOutputPolicyDropDown     matlab.ui.control.DropDown
        RuntimeOutputPolicyLabel        matlab.ui.control.Label
        RuntimeRoisEditField            matlab.ui.control.EditField
        RuntimeRoisLabel                matlab.ui.control.Label
        RuntimeFramesEditField          matlab.ui.control.EditField
        RuntimeFramesLabel              matlab.ui.control.Label
        RuntimeFovsEditField            matlab.ui.control.EditField
        RuntimeFovsLabel                matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        SmokeTestButton                 matlab.ui.control.Button
        RuntimeAvailableTextArea        matlab.ui.control.TextArea
        RuntimeAvailableLabel           matlab.ui.control.Label
        RuntimeBrowseRawDataButton      matlab.ui.control.Button
        RuntimeRawDataEditField         matlab.ui.control.EditField
        RuntimeRawDataLabel             matlab.ui.control.Label
        RuntimeBrowseExistingButton     matlab.ui.control.Button
        RuntimeProjectSelectDropDown    matlab.ui.control.DropDown
        RuntimeProjectTargetEditField   matlab.ui.control.EditField
        RuntimeProjectTargetLabel       matlab.ui.control.Label
        RuntimeSourceDropDown           matlab.ui.control.DropDown
        RuntimeSourceLabel              matlab.ui.control.Label
        TemplateidEditField             matlab.ui.control.EditField
        TemplateidEditFieldLabel        matlab.ui.control.Label
        MonitorTab                      matlab.ui.container.Tab
        ProgressionStatusLabel          matlab.ui.control.Label
        ProgressionLabel                matlab.ui.control.Label
        ConsoleTextArea                 matlab.ui.control.TextArea
        ConsoleTextAreaLabel            matlab.ui.control.Label
        CancelrunButton                 matlab.ui.control.Button
        RunParamsButton                 matlab.ui.control.Button
        RunLogButton                    matlab.ui.control.Button
        OpenRunFolderButton             matlab.ui.control.Button
        ReviewRunButton                 matlab.ui.control.Button
    end

    properties (Access = private)
        Data struct = struct( ...
            'nodes', struct([]), ...
            'edges', struct('from',{},'to',{},'fromPort',{},'toPort',{},'condition',{}), ...
            'runMode', false)
        SelectedNodeIndex double = NaN
        NodeCounter double = 0
        BlockHandles = gobjects(0)
        GhostHandles = gobjects(0)
        EdgeHandles = gobjects(0)
        ModuleContextMenu matlab.ui.container.ContextMenu
        GraphContextMenu matlab.ui.container.ContextMenu
        ModulesMenu = []
        LoadpipelineMenu = []
        LoadrecentpipelineMenu = []
        LoadrunMenu = []
        GenerateDocumentationMenu matlab.ui.container.Menu
        OpenDocumentationMenu matlab.ui.container.Menu
        DynamicModuleTabs = gobjects(0)
        AvailableModules cell = {}
        ModuleCatalogueItems struct = struct([])
        ModuleCatalogueDiagnostics struct = struct([])
        ModuleCataloguePackagePaths cell = {}
        ModuleCatalogueFromCache logical = false
        IsRefreshingTabs logical = false
        ModuleTabRefreshSuspended logical = false
        ModuleTabRefreshBatchDepth double = 0
        ModuleTabRefreshPending logical = false
        IsRedrawingGraph logical = false
        HasStartupPipeline logical = false
        OpeningPipeline logical = true
        RuntimeInventoryMetadataOnly logical = true
        SavedRuntimeInventoryContext struct = struct()
        RuntimeFieldHandles struct = struct()
        RuntimeButtonHandles struct = struct()
        RuntimeValues struct = struct()
        RuntimeNodeParams struct = struct()
        RuntimeParseInfo struct = struct()
        RuntimeProgressDialog = []
        HubFieldHandles struct = struct()
        RunArtifactButtonHandles struct = struct()
        CurrentPipeline = []
        CurrentPipelinePath char = ''
        CurrentPipelineWorkspaceVar char = ''
        CurrentPipelineIsRunSnapshot logical = false
        IsPipelineDirty logical = false
        CurrentRun = []
        CurrentRunPath char = ''
        IsRunDirty logical = false
        CurrentRunIsSeed logical = false
        CurrentRunSourceId char = ''
        LastStatusDetail char = ''
        CurrentProject = []
        CurrentProjectVarName char = ''
        RuntimeDataSeriesCache struct = struct('key', '', 'names', {{}}, 'sampledRoiCount', 0, 'sampledFovCount', 0);
        RuntimeLoadedRoiData cell = {}
        RuntimeStoredChannelsCache = []
        NumericTraceInventory = []
        BindingEditorClassifier = []
        BindingEditorCallback = []
        RuntimeInventoryRefreshSuspended logical = false
        RuntimeModeUnlocked logical = false
        RuntimeInputModeLocked logical = false
        RuntimeInputModeLockReason char = ''
        LastValidationReport struct = struct()
        LastValidationOk logical = false
        WorkflowRawProject = []
        WorkflowRawProjectPath char = ''
        RoiManualPreviewHandles cell = {}
        RoiManualPreviewListeners cell = {}
        RoiManualSelectedRectangle double = NaN
        ActiveRunMode char = ''
        RunLaunchInProgress logical = false
        ActiveRunCancelRequested logical = false
        HubRunMonitorToken char = ''
        HubRunMonitorJobId char = ''
        HubRunMonitorLastStatus char = ''
        HubRunUiLocked logical = false
        HubRunFileMonitorFuture = []
        HubRunFileMonitorEventQueue = []
        HubRunFileMonitorEventListener = []
        HubRunFileMonitorResultQueue = []
        LocalRunSubmission struct = struct()
        BatchPrototypeMode logical = false
        BatchPrototypeModal logical = false
        ExplicitRuntimeRoiList = []
        RuntimeClassifierSource = []
        RuntimeClassifierSourceRef = struct('key', '', 'label', '', 'kind', '', 'path', '', 'id', '')
        RuntimeClassifierChoices = struct('key', {}, 'label', {}, 'kind', {}, 'path', {}, 'id', {}, 'object', {})
        TypeDropDown struct = struct()
        SubtypeDropDown struct = struct()
        AdvancedmodeCheckBox struct = struct()
        UIWorkspacePipelineTable struct = struct()
        MonitorProgressGauge = []
        MonitorLastProgress double = 0
        MonitorLastProgressConsoleSignature char = ''
        MonitorLastHubConsoleText char = ''
        MonitorHasModuleProgress logical = false
        MonitorRunStartTic = []
        MonitorEtaSeconds double = NaN
    end

    properties (Access = public)
        ViewerApplyCallback = []
        ViewerRoiCallback = []
        ViewerBridgeMenu = []
        ViewerOriginalClose = []
        ViewerDefinitionOnly = false
    end
    methods (Access = public)
        function busy = viewerHasActiveRun(app)
            busy = ~isempty(app.ActiveRunMode) || app.RunLaunchInProgress;
        end
        function configureViewerBridge(app,onApply,onRoi,definitionOnly)
            if nargin < 4, definitionOnly = false; end
            app.ViewerDefinitionOnly = logical(definitionOnly);
            app.ViewerOriginalClose = app.UIFigure.CloseRequestFcn;
            app.ViewerApplyCallback = onApply; app.ViewerRoiCallback = onRoi;
            if ~isempty(app.CurrentRun), app.CurrentRunIsSeed = isempty(app.CurrentRun.path); end
            app.UIFigure.WindowStyle = 'normal';
            app.RuntimeProjectSelectDropDown.Enable = 'off';
            app.RuntimeProjectTargetEditField.Enable = 'off';
            if app.ViewerDefinitionOnly
                setRuntimeModeUnlocked(app,false);
                for name = {'NewRunButton','RunButton','SmokeTestButton','LoadrunMenu','SaverunMenu','SaverunasMenu'}
                    app.(name{1}).Enable = 'off';
                end
                app.UIFigure.Name = 'Pipeline Designer';
            end
            app.ViewerBridgeMenu = uimenu(app.UIFigure,'Text','Viewer');
            uimenu(app.ViewerBridgeMenu,'Text','Apply Settings and Return', ...
                'MenuSelectedFcn',@(~,~)app.returnToViewer());
            if app.ViewerDefinitionOnly
                uimenu(app.ViewerBridgeMenu,'Text','Discard Changes and Return', ...
                    'MenuSelectedFcn',@(~,~)app.discardViewerDraft());
            end
            app.UIFigure.CloseRequestFcn = @(~,~)app.returnToViewer();
            % ROI controls are built before the viewer callback is attached.
            % Rebuild them against the shared-image capability, without
            % unlocking run creation or execution in definition mode.
            refreshModuleTabs(app);
        end
        function selectViewerModule(app,nodeId)
            assert(~app.viewerHasActiveRun(),'pipeline2:ViewerBusy','The run is active.');
            index = find(strcmp({app.Data.nodes.id},char(string(nodeId))));
            assert(isscalar(index),'pipeline2:ViewerModule','Select a uniquely identified module.');
            app.MainTabGroup.SelectedTab = app.BuildTab;
            selectNode(app,index);
        end
        function changeViewerModuleType(app,nodeId,choice)
            app.selectViewerModule(nodeId);
            if nargin < 3, choice = chooseModuleFromLibrary(app,'Change module type'); end
            if isempty(choice), return; end
            changeSelectedModuleType(app,choice.type,choice.pkg,getField(app,choice,'paramsPatch',struct()));
            app.selectViewerModule(nodeId);
        end
        function discardViewerDraft(app)
            assert(~app.viewerHasActiveRun(),'pipeline2:ViewerBusy','The run is active.');
            app.clearViewerBridge(); delete(app);
        end
        function clearViewerBridge(app)
            app.ViewerApplyCallback = []; app.ViewerRoiCallback = [];
            app.ViewerDefinitionOnly = false;
            if ~isempty(app.ViewerBridgeMenu) && isvalid(app.ViewerBridgeMenu), delete(app.ViewerBridgeMenu); end
            if isvalid(app.UIFigure), app.UIFigure.CloseRequestFcn = app.ViewerOriginalClose; end
        end
        function state = exportViewerDraft(app)
            state = struct('project',app.CurrentProject,'pipeline',buildPipelineStruct(app), ...
                'ctx',struct(),'settings',struct(),'busy',~isempty(app.ActiveRunMode) || app.RunLaunchInProgress);
            if ~isempty(app.CurrentRun) && isstruct(app.CurrentRun.ctx)
                state.ctx = stripTransientRunContext(app,app.CurrentRun.ctx);
            end
            settings = struct('runId',app.TemplateidEditField.Value, ...
                'inputSourceMode',getRuntimeValue(app,'inputSourceMode'), ...
                'rawDataPath',getRuntimeValue(app,'rawDataPath'), ...
                'fovs',getRuntimeValue(app,'fovs'),'rois',getRuntimeValue(app,'rois'), ...
                'frames',getRuntimeValue(app,'frames'), ...
                'runPolicy',resumeModeToRunPolicy(app,app.ResumeoptionsDropDown.Value), ...
                'outputPolicy',getRuntimeValue(app,'outputPolicy'), ...
                'gpuPolicy',app.ExecutionDropDown.Value,'executionTarget',runtimeExecutionTarget(app), ...
                'description','');
            settings.gpuPolicy = lower(char(string(settings.gpuPolicy)));
            if strcmp(settings.gpuPolicy,'auto'), settings.gpuPolicy = 'module_default'; end
            if ~isempty(app.CurrentRun), settings.description = app.CurrentRun.description; end
            overrides = buildRunNodeParams(app); records = struct('id',{},'params',{});
            for k = 1:numel(app.Data.nodes)
                id = char(string(app.Data.nodes(k).id)); key = matlab.lang.makeValidName(id);
                if isfield(overrides,key), records(end+1) = struct('id',id,'params',overrides.(key)); end %#ok<AGROW>
            end
            if ~isfield(state.ctx,'run'), state.ctx.run = struct(); end
            settings.selectedNodes = selectedRunNodeIds(app);
            state.ctx.run.selectedNodes = settings.selectedNodes;
            state.ctx.run.nodeParams = records;
            state.ctx.run.intent = getRuntimeValue(app,'intent');
            state.ctx.run.control = buildRunControlPolicy(app,settings.executionTarget);
            if strcmp(settings.executionTarget,'hub'), state.ctx.hub = hubSettingsFromUi(app); end
            state.settings = settings;
        end
        function returnToViewer(app)
            if isempty(app.ViewerApplyCallback), delete(app); return; end
            try
                assert(isempty(app.ActiveRunMode) && ~app.RunLaunchInProgress, ...
                    'pipeline2:ViewerBusy','The run is active. Keep this window open until it finishes.');
                progress = openRuntimeProgress(app,'Run Settings','Applying settings to viewer...');
                release = onCleanup(@()closeRuntimeProgress(app,progress)); %#ok<NASGU>
                state = app.exportViewerDraft();
                assert(~state.busy,'pipeline2:ViewerBusy','The run is active. Keep this window open until it finishes.');
                detecdivui.Callback.call(app.ViewerApplyCallback,state); clear release;
                app.clearViewerBridge(); delete(app);
            catch err
                uialert(app.UIFigure,err.message,'Run Settings');
            end
        end
        function acceptViewerRois(app,nodeId,ctx)
            if ~isvalid(app), return; end
            index = find(strcmp({app.Data.nodes.id},nodeId),1);
            if isempty(index), return; end
            applyRoiDefinitionEditorResult(app,nodeId,lower(app.Data.nodes(index).type),ctx);
            refreshAfterModelChange(app); app.UIFigure.Visible = 'on';
        end
        function acceptViewerRoiDraft(app,nodeId,identity,ctx)
            index = find(strcmp({app.Data.nodes.id},nodeId),1);
            assert(~isempty(index) && strcmp(jsonencode(app.Data.nodes(index)),identity.node) && ...
                isequal(app.CurrentPipeline,identity.pipeline) && isequal(app.CurrentRun,identity.run) && ...
                strcmp(app.CurrentPipelinePath,identity.path) && strcmp(currentPipelineName(app),identity.name) && ...
                strcmp(app.viewerRoiSignature(nodeId),identity.effective), ...
                'pipeline2:ROIContext','The module changed. Reopen its ROI editor before applying this draft.');
            assert(~app.viewerHasActiveRun(),'pipeline2:ViewerBusy','The run is active.');
            app.acceptViewerRois(nodeId,ctx);
        end
        function requestViewerRois(app,nodeId,params)
            index = find(strcmp({app.Data.nodes.id},nodeId),1);
            if isempty(index) || isempty(app.ViewerRoiCallback), return; end
            nodes = pipelineApplyRunNodeParams(app.Data.nodes,buildRunNodeParams(app)); node = nodes(index);
            if nargin >= 3, node.params = params; end
            if app.RuntimeModeUnlocked && isempty(getField(app,node.params,'fovIndex',[]))
                selection = parseRuntimeFovSelection(app,getRuntimeValue(app,'fovs'));
                if ~isempty(selection), node.params.fovIndex = selection; end
            end
            identity = struct('node',jsonencode(app.Data.nodes(index)),'pipeline',app.CurrentPipeline, ...
                'run',app.CurrentRun,'path',app.CurrentPipelinePath,'name',currentPipelineName(app), ...
                'effective',app.viewerRoiSignature(nodeId));
            callback = detecdivui.Callback(app,'acceptViewerRoiDraft',nodeId,identity);
            source = struct('inputSourceMode',getRuntimeValue(app,'inputSourceMode'), ...
                'rawDataPath',getRuntimeValue(app,'rawDataPath'));
            detecdivui.Callback.call(app.ViewerRoiCallback,app.CurrentProject,node,callback,source);
            % Keep the editor available while the main Image owns the tool.
            % Failure to resolve an image context must never hide the editor.
        end
        function signature = viewerRoiSignature(app,nodeId)
            nodes = pipelineApplyRunNodeParams(app.Data.nodes,buildRunNodeParams(app));
            index = find(strcmp({nodes.id},nodeId),1);
            assert(~isempty(index),'pipeline2:ROIContext','The ROI module is no longer available.');
            state = struct('node',nodes(index));
            for key = {'inputSourceMode','rawDataPath','fovs','rois','frames'}
                state.(key{1}) = getRuntimeValue(app,key{1});
            end
            signature = jsonencode(state);
        end
    end
    methods (Access = private)

        function startupFcn(app)
            reportPipelineOpenStage(app, sprintf(['Loading installed modules...\n' ...
                'Checking module locations. Network storage may take longer.']));
            app.UIFigure.Name = guiAppName(app);
            app.AvailableModules = defaultModuleLibrary(app);

            reportPipelineOpenStage(app, 'Preparing editor controls...');
            configureControls(app);
            refreshAvailableModuleTable(app);
            refreshSelectedModuleTable(app);
            % A supplied pipeline is loaded immediately after startup. Avoid
            % first rendering and validating an empty placeholder graph.
            if ~app.HasStartupPipeline
                redrawGraph(app);
                refreshValidationReport(app, false);
            end
            markPipelineDirty(app, isempty(app.CurrentPipelinePath));
        end

        function applyStartupArguments(app, varargin)
            if isempty(varargin)
                return;
            end
            [opts, positionalArgs] = parseStartupRuntimeOptions(app, varargin{:});
            app.ViewerDefinitionOnly = opts.viewerDefinitionOnly;
            projectObj = [];
            pipeObj = [];
            runObj = [];
            for i = 1:numel(positionalArgs)
                arg = positionalArgs{i};
                if isempty(arg)
                    continue;
                elseif isa(arg, 'shallow')
                    projectObj = arg;
                elseif isa(arg, 'pipeline')
                    pipeObj = arg;
                elseif isa(arg, 'pipelineRun')
                    runObj = arg;
                elseif ischar(arg) || isstring(arg)
                    argPath = char(string(arg));
                    reportPipelineOpenStage(app, 'Reading saved pipeline or run...', argPath);
                    if exist(argPath, 'file') == 2
                        try
                            [~, ~, ext] = fileparts(argPath);
                            if strcmpi(ext, '.json') && strcmpi(getFileName(app, argPath), 'run.json')
                                [candidateRun, msg] = pipelineRunLoad(argPath);
                                if isempty(candidateRun)
                                    error('pipeline2:RunLoadFailed', '%s', msg);
                                end
                                runObj = candidateRun;
                            else
                                [candidate, msg] = pipelineLoad(argPath);
                                if isempty(candidate)
                                    error('pipeline2:PipelineLoadFailed', '%s', msg);
                                end
                                pipeObj = candidate;
                            end
                        catch ME
                            uialert(app.UIFigure, ME.message, 'Open pipeline/run', 'Icon', 'error');
                        end
                    end
                end
            end

            hasPipelineContext = ~isempty(pipeObj) && isa(pipeObj, 'pipeline');
            hasRunContext = ~isempty(runObj) && isa(runObj, 'pipelineRun');
            if hasRunContext && isstruct(runObj.ctx)
                app.SavedRuntimeInventoryContext = runObj.ctx;
            end
            hasExplicitRuntimeContext = hasRunContext || opts.unlockRuntime || ...
                (isfield(opts, 'projectPath') && ~isempty(strtrim(opts.projectPath))) || ...
                (isfield(opts, 'inputMode') && ~isempty(strtrim(opts.inputMode)));
            shouldBindStartupProject = ~isempty(projectObj) && isa(projectObj, 'shallow') && ...
                (~hasPipelineContext || hasExplicitRuntimeContext);

            if shouldBindStartupProject
                reportPipelineOpenStage(app, 'Linking the loaded project...');
                projectVarFallback = 'shallowObj';
                if isfield(opts, 'projectVarName') && ~isempty(strtrim(opts.projectVarName))
                    projectVarFallback = opts.projectVarName;
                end
                varName = findWorkspaceVarForObject(app, projectObj, projectVarFallback);
                bindCurrentProject(app, projectObj, varName);
            end
            if ~isempty(pipeObj) && isa(pipeObj, 'pipeline')
                loadPipelineFromObject(app, pipeObj, false);
                try
                    addRecentPipelinePath(app, fullfile(pipeObj.path, 'pipeline.json'));
                catch
                end
            end
            if ~isempty(runObj) && isa(runObj, 'pipelineRun')
                if isempty(pipeObj)
                    reportPipelineOpenStage(app, 'Locating the pipeline template for this run...');
                    [pipeFromRun, msg] = resolvePipelineFromRunForUi(app, runObj);
                    if isempty(pipeFromRun)
                        uialert(app.UIFigure, msg, 'Open pipeline run', 'Icon', 'warning');
                    else
                        loadPipelineFromObject(app, pipeFromRun, false);
                        try
                            addRecentPipelinePath(app, fullfile(pipeFromRun.path, 'pipeline.json'));
                        catch
                        end
                    end
                end
                reportPipelineOpenStage(app, 'Restoring saved run settings...');
                loadRunIntoUi(app, runObj);
                if ~isfield(opts, 'editExistingRun') || ~logical(opts.editExistingRun)
                    markCurrentRunAsSeed(app, runObj);
                else
                    app.CurrentRunIsSeed = false;
                    app.CurrentRunSourceId = '';
                    setRuntimeStatus(app, sprintf('Editing existing run: %s\nRun/Save updates this run configuration.', char(string(runObj.runId))));
                end
            end
            reportPipelineOpenStage(app, 'Applying run selections and execution settings...');
            applyStartupRuntimeOptions(app, opts);
            setRuntimeModeUnlocked(app, (~isempty(runObj) && isa(runObj, 'pipelineRun')) || opts.unlockRuntime);
        end

        function [opts, positionalArgs] = parseStartupRuntimeOptions(app, varargin) %#ok<INUSD>
            opts = struct('inputMode', '', 'lockInputMode', false, 'lockReason', '', ...
                'projectPath', '', 'rawDataPath', '', 'unlockRuntime', false, ...
                'fovs', '', 'frames', '', 'rois', '', 'channels', '', 'roiObjects', [], ...
                'outputPolicy', '', 'executionTarget', '', 'gpuPolicy', '', 'runId', '', ...
                'intent', '', ...
                'batchPrototype', false, 'modal', false, 'editExistingRun', false, ...
                'projectVarName', '', 'viewerDefinitionOnly', false);
            positionalArgs = {};
            i = 1;
            while i <= numel(varargin)
                arg = varargin{i};
                if (ischar(arg) || (isstring(arg) && isscalar(arg))) && i < numel(varargin)
                    key = lower(strrep(strrep(char(string(arg)), '-', ''), '_', ''));
                    value = varargin{i + 1};
                    consumed = true;
                    switch key
                        case 'viewerdefinitiononly'
                            opts.viewerDefinitionOnly = logicalStartupOption(app,value);
                        case {'inputmode','runtimemode','mode','usemode'}
                            opts.inputMode = normalizeStartupInputMode(app, value);
                            opts.unlockRuntime = true;
                        case {'lockinputmode','lockmode','fixedinputmode'}
                            opts.lockInputMode = logicalStartupOption(app, value);
                            opts.unlockRuntime = opts.unlockRuntime || opts.lockInputMode;
                        case {'lockreason','inputmodereason','reason'}
                            opts.lockReason = char(string(value));
                        case {'projectpath','project','targetproject','projecttarget'}
                            if ischar(value) || isstring(value)
                                opts.projectPath = char(string(value));
                                opts.unlockRuntime = true;
                            else
                                consumed = false;
                            end
                        case {'projectvarname','projectvar','workspaceprojectvar','workspacevar'}
                            if ischar(value) || isstring(value)
                                opts.projectVarName = matlab.lang.makeValidName(char(string(value)));
                            else
                                consumed = false;
                            end
                        case {'rawdatapath','rawdata','rawimagefolder','rawfolder'}
                            if ischar(value) || isstring(value)
                                opts.rawDataPath = char(string(value));
                                opts.unlockRuntime = true;
                            else
                                consumed = false;
                            end
                        case {'fovs','fov','positions','position'}
                            opts.fovs = startupSelectionText(app, value);
                            opts.unlockRuntime = true;
                        case {'frames','frame'}
                            opts.frames = startupSelectionText(app, value);
                            opts.unlockRuntime = true;
                        case {'rois','roi','roilist'}
                            opts.rois = startupSelectionText(app, value);
                            opts.unlockRuntime = true;
                        case {'roiobjects','explicitrois','roihandles'}
                            opts.roiObjects = value;
                            if isempty(opts.rois)
                                try
                                    opts.rois = sprintf('1:%d', numel(value));
                                catch
                                    opts.rois = '';
                                end
                            end
                            opts.unlockRuntime = true;
                        case {'channels','channel'}
                            opts.channels = startupSelectionText(app, value);
                            opts.unlockRuntime = true;
                        case {'outputpolicy','existingpolicy','writepolicy'}
                            opts.outputPolicy = char(string(value));
                            opts.unlockRuntime = true;
                        case {'executiontarget','runtarget','target'}
                            opts.executionTarget = char(string(value));
                            opts.unlockRuntime = true;
                        case {'gpupolicy','execution','compute'}
                            opts.gpuPolicy = char(string(value));
                            opts.unlockRuntime = true;
                        case {'runid','runname'}
                            opts.runId = char(string(value));
                            opts.unlockRuntime = true;
                        case {'intent','operation','task','runtype','classifierintent'}
                            opts.intent = normalizeStartupIntent(app, value);
                            opts.unlockRuntime = true;
                        case {'unlockruntime','newrun','runtimeunlocked'}
                            opts.unlockRuntime = logicalStartupOption(app, value);
                        case {'editexistingrun','editrun','updaterun'}
                            opts.editExistingRun = logicalStartupOption(app, value);
                            opts.unlockRuntime = opts.unlockRuntime || opts.editExistingRun;
                        case {'batchprototype','prototype','prototypeconfig','configureprototype'}
                            opts.batchPrototype = logicalStartupOption(app, value);
                            opts.unlockRuntime = opts.unlockRuntime || opts.batchPrototype;
                        case {'modal','windowmodal'}
                            opts.modal = logicalStartupOption(app, value);
                        otherwise
                            consumed = false;
                    end
                    if consumed
                        i = i + 2;
                        continue;
                    end
                end
                if ischar(arg) || (isstring(arg) && isscalar(arg))
                    txt = char(string(arg));
                    if exist(txt, 'file') ~= 2 && exist(txt, 'dir') ~= 7
                        try
                            mode = normalizeStartupInputMode(app, txt);
                            opts.inputMode = mode;
                            opts.unlockRuntime = true;
                            i = i + 1;
                            continue;
                        catch
                        end
                    end
                end
                positionalArgs{end+1} = arg; %#ok<AGROW>
                i = i + 1;
            end
        end

        function txt = startupSelectionText(app, value) %#ok<INUSD>
            if isempty(value)
                txt = '';
            elseif ischar(value) || isstring(value)
                txt = char(string(value));
            elseif isnumeric(value) || islogical(value)
                values = double(value(:)');
                if isempty(values)
                    txt = '';
                else
                    txt = strjoin(arrayfun(@(x)sprintf('%g', x), values, 'UniformOutput', false), ',');
                end
            elseif iscell(value)
                txt = strjoin(cellstr(string(value(:)')), ',');
            else
                try
                    txt = char(string(value));
                catch
                    txt = '';
                end
            end
        end

        function mode = normalizeStartupInputMode(app, value) %#ok<INUSD>
            txt = lower(strtrim(char(string(value))));
            txt = strrep(txt, '-', '_');
            txt = strrep(txt, ' ', '_');
            switch txt
                case {'project','existing','existing_project','existing_rois','read_project','read_from_existing_project','project_input'}
                    mode = 'existing_rois';
                case {'raw','raw_data','raw_dataloader','parse_raw','parse_raw_images','raw_to_project','parse_raw_images_into_project'}
                    mode = 'raw_dataloader';
                case {'classifier','classi','classifier_rois','classifier_roi','attached_classifier','attached_rois','classifier_input'}
                    mode = 'classifier_rois';
                otherwise
                    error('pipeline2:InvalidInputMode', ...
                        'Invalid InputMode "%s". Use "project", "raw", or "classifier".', char(string(value)));
            end
        end

        function intent = normalizeStartupIntent(app, value) %#ok<INUSD>
            txt = lower(strtrim(char(string(value))));
            txt = strrep(txt, '-', '_');
            txt = strrep(txt, ' ', '_');
            switch txt
                case {'','infer','inference','classify','classification','run'}
                    intent = 'infer';
                case {'validate','validation','val','test','evaluate','eval'}
                    intent = 'validate';
                case {'train','training','fit'}
                    intent = 'train';
                otherwise
                    error('pipeline2:InvalidIntent', ...
                        'Invalid Intent "%s". Use "train", "validate", or "infer".', char(string(value)));
            end
        end

        function tf = logicalStartupOption(app, value) %#ok<INUSD>
            if islogical(value) || isnumeric(value)
                tf = logical(value);
                return;
            end
            txt = lower(strtrim(char(string(value))));
            tf = any(strcmp(txt, {'true','1','yes','on','locked','lock','enable','enabled'}));
        end

        function applyStartupRuntimeOptions(app, opts)
            if ~isstruct(opts)
                return;
            end
            if isfield(opts, 'projectPath') && ~isempty(strtrim(opts.projectPath))
                setRuntimeValuePreserveParse(app, 'projectPath', opts.projectPath);
                try
                    if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                        bindProjectFromPath(app, opts.projectPath, false);
                    end
                catch
                end
            end
            if isfield(opts, 'rawDataPath') && ~isempty(strtrim(opts.rawDataPath))
                setRuntimeValuePreserveParse(app, 'rawDataPath', opts.rawDataPath);
            end
            if isfield(opts, 'fovs') && ~isempty(strtrim(opts.fovs))
                setRuntimeValuePreserveParse(app, 'fovs', opts.fovs);
            end
            if isfield(opts, 'frames') && ~isempty(strtrim(opts.frames))
                setRuntimeValuePreserveParse(app, 'frames', opts.frames);
            end
            if isfield(opts, 'rois') && ~isempty(strtrim(opts.rois))
                setRuntimeValuePreserveParse(app, 'rois', opts.rois);
            end
            if isfield(opts, 'roiObjects') && ~isempty(opts.roiObjects)
                app.ExplicitRuntimeRoiList = opts.roiObjects;
                if ~isfield(opts, 'rois') || isempty(strtrim(opts.rois))
                    try
                        setRuntimeValuePreserveParse(app, 'rois', sprintf('1:%d', numel(opts.roiObjects)));
                    catch
                    end
                end
            end
            if isfield(opts, 'channels') && ~isempty(strtrim(opts.channels))
                setRuntimeValuePreserveParse(app, 'channels', opts.channels);
            end
            if isfield(opts, 'outputPolicy') && ~isempty(strtrim(opts.outputPolicy))
                setRuntimeValuePreserveParse(app, 'outputPolicy', opts.outputPolicy);
                app.RuntimeValues.outputPolicyUserChosen = true;
            end
            if isfield(opts, 'executionTarget') && ~isempty(strtrim(opts.executionTarget))
                setRuntimeExecutionTarget(app, opts.executionTarget);
            end
            if isfield(opts, 'gpuPolicy') && ~isempty(strtrim(opts.gpuPolicy))
                gpuPolicy = char(string(opts.gpuPolicy));
                try
                    if any(strcmp(app.ExecutionDropDown.ItemsData, gpuPolicy))
                        app.ExecutionDropDown.Value = gpuPolicy;
                    elseif any(strcmp(app.ExecutionDropDown.Items, gpuPolicy))
                        app.ExecutionDropDown.Value = gpuPolicy;
                    else
                        switch lower(strtrim(gpuPolicy))
                            case 'gpu'
                                app.ExecutionDropDown.Value = 'GPU';
                            case 'cpu'
                                app.ExecutionDropDown.Value = 'CPU';
                            otherwise
                                app.ExecutionDropDown.Value = 'Auto';
                        end
                    end
                catch
                end
            end
            if isfield(opts, 'runId') && ~isempty(strtrim(opts.runId))
                runtimeRunIdChanged(app, opts.runId);
            end
            if isfield(opts, 'intent') && ~isempty(strtrim(opts.intent))
                app.RuntimeValues.intent = opts.intent;
                if strcmpi(char(string(opts.intent)), 'train') && ...
                        (isempty(app.CurrentRun) || app.CurrentRunIsSeed) && ...
                        isprop(app, 'ResumeoptionsDropDown') && ~isempty(app.ResumeoptionsDropDown)
                    try
                        app.ResumeoptionsDropDown.Value = 'Restart from scratch';
                        applyRecommendedOutputPolicyForResume(app);
                    catch
                    end
                end
            end
            if isfield(opts, 'inputMode') && ~isempty(strtrim(opts.inputMode))
                applyRuntimeInputSourceMode(app, opts.inputMode);
            end
            if isfield(opts, 'lockInputMode') && opts.lockInputMode
                app.RuntimeInputModeLocked = true;
                if isfield(opts, 'lockReason') && ~isempty(strtrim(opts.lockReason))
                    app.RuntimeInputModeLockReason = opts.lockReason;
                else
                    app.RuntimeInputModeLockReason = 'Input mode was fixed by the app launch context.';
                end
            end
            if isfield(opts, 'batchPrototype') && opts.batchPrototype
                app.BatchPrototypeMode = true;
                app.BatchPrototypeModal = isfield(opts, 'modal') && logical(opts.modal);
                setRuntimeModeUnlocked(app, true);
                if isempty(app.CurrentRun) || ~isa(app.CurrentRun, 'pipelineRun')
                    app.CurrentRunIsSeed = true;
                    try
                        runId = suggestNextRunIdForUi(app);
                        app.TemplateidEditField.Value = runId;
                        app.RuntimeValues.runId = runId;
                    catch
                    end
                end
                app.NewRunButton.Text = 'Use Prototype';
                try
                    app.UIFigure.Name = [guiAppName(app) ' - Batch prototype'];
                catch
                end
                try
                    app.MainTabGroup.SelectedTab = app.RunTab;
                catch
                end
                setRuntimeStatus(app, sprintf('Batch prototype mode.\nSet runtime parameters and target, then click Use Prototype.'));
                applyBatchPrototypeUiRestrictions(app);
            end
            updateRuntimeInputStates(app);
        end

        function name = getFileName(app, filePath) %#ok<INUSD>
            [~, n, e] = fileparts(char(string(filePath)));
            name = [n e];
        end

        function [pipeObj, msg] = resolvePipelineFromRunForUi(app, runObj)
            pipeObj = [];
            msg = 'Could not resolve pipeline template for this run.';
            if isempty(runObj) || ~isa(runObj, 'pipelineRun')
                return;
            end

            runSnapshotPaths = {};
            try
                if isstruct(runObj.pipelineRef) && isfield(runObj.pipelineRef, 'path') && ~isempty(runObj.pipelineRef.path)
                    runSnapshotPaths{end+1} = char(string(runObj.pipelineRef.path)); %#ok<AGROW>
                end
            catch
            end
            try
                if ~isempty(runObj.templatePath)
                    runSnapshotPaths{end+1} = char(string(runObj.templatePath)); %#ok<AGROW>
                end
            catch
            end
            try
                if isstruct(runObj.ctx) && isfield(runObj.ctx, 'pipelineRef') && isstruct(runObj.ctx.pipelineRef) && ...
                        isfield(runObj.ctx.pipelineRef, 'path') && ~isempty(runObj.ctx.pipelineRef.path)
                    runSnapshotPaths{end+1} = char(string(runObj.ctx.pipelineRef.path)); %#ok<AGROW>
                end
            catch
            end
            % Usually the project already names its template. Try those
            % precise paths before enumerating network folders or probing
            % every historical/recent location. Preserve the historical
            % full-graph preference used by partial runs.
            directPaths = projectPipelineTemplatePathsForRun(app, runObj, false);
            % The run's exact reference is preferable to several speculative
            % project paths, each of which may incur a network timeout.
            directPaths = unique([runSnapshotPaths directPaths], 'stable');
            templateId = runTemplateIdForUi(app, runObj);
            for iPath = 1:numel(directPaths)
                candidatePath = char(string(directPaths{iPath}));
                if isRunSnapshotPipelinePath(app, candidatePath)
                    continue;
                end
                candidatePath = expandRunPipelineTemplatePaths(app, {candidatePath});
                candidatePath = fliplr(candidatePath); % mapped local path first
                for iLocal = 1:numel(candidatePath)
                    reportPipelineOpenStage(app, 'Reading the pipeline template...', candidatePath{iLocal});
                    [candidate, ~] = pipelineLoad(candidatePath{iLocal});
                    if isempty(candidate) || (~isempty(templateId) && ...
                            ~strcmpi(char(string(candidate.strid)), templateId))
                        continue;
                    end
                    historicalPipe = widestProjectRunPipelineSpecForUi(app, runObj);
                    if shouldPreferHistoricalRunPipelineSpec(app, candidate, historicalPipe, runObj)
                        candidate = historicalPipe;
                    end
                    pipeObj = candidate;
                    msg = '';
                    return;
                end
            end
            reportPipelineOpenStage(app, 'Searching other saved template locations...');
            candidatePaths = [projectPipelineTemplatePathsForRun(app, runObj) ...
                classifierPipelineTemplatePathsForRun(app, runObj) ...
                recentPipelineTemplatePathsForRun(app, runObj) ...
                runSnapshotPaths]; %#ok<AGROW>
            candidatePaths = expandRunPipelineTemplatePaths(app, candidatePaths);
            [pipeObj, loadMsg] = loadBestPipelineTemplateCandidate(app, candidatePaths, runObj);
            historicalPipe = widestProjectRunPipelineSpecForUi(app, runObj);
            if ~isempty(pipeObj)
                if shouldPreferHistoricalRunPipelineSpec(app, pipeObj, historicalPipe, runObj)
                    pipeObj = historicalPipe;
                end
                msg = '';
                return;
            end
            if ~isempty(loadMsg)
                msg = loadMsg;
            end
            if ~isempty(historicalPipe)
                pipeObj = historicalPipe;
                msg = '';
                return;
            end

            try
                spec = runObj.ctx.pipelineSpec;
                if isstruct(spec) && isfield(spec, 'nodes') && ~isempty(spec.nodes)
                    pipeObj = pipeline('', char(string(getNestedRunField(app, runObj, {'pipelineRef','id'}, defaultPipelineTemplateName(app)))), 1);
                    pipeObj.nodes = spec.nodes;
                    if isfield(spec, 'edges')
                        pipeObj.edges = spec.edges;
                    end
                    if isfield(spec, 'branches')
                        pipeObj.branches = spec.branches;
                    end
                    msg = '';
                    return;
                end
            catch
            end
        end

        function pipeObj = widestProjectRunPipelineSpecForUi(app, runObj)
            pipeObj = [];
            shallowObj = app.CurrentProject;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow') || ...
                    ~isfield(shallowObj.processing, 'pipelineRun') || isempty(shallowObj.processing.pipelineRun)
                return;
            end
            templateId = runTemplateIdForUi(app, runObj);
            bestSpec = [];
            bestCount = 0;
            bestName = templateId;
            bestPath = '';
            try
                runs = shallowObj.processing.pipelineRun;
                for iRun = 1:numel(runs)
                    r = runs(iRun);
                    if isempty(r) || ~isa(r, 'pipelineRun') || ~sameRunTemplateForUi(app, r, templateId)
                        continue;
                    end
                    if ~isstruct(r.ctx) || ~isfield(r.ctx, 'pipelineSpec') || ~isstruct(r.ctx.pipelineSpec) || ...
                            ~isfield(r.ctx.pipelineSpec, 'nodes') || isempty(r.ctx.pipelineSpec.nodes)
                        continue;
                    end
                    nNodes = numel(r.ctx.pipelineSpec.nodes);
                    if nNodes <= bestCount
                        continue;
                    end
                    bestSpec = r.ctx.pipelineSpec;
                    bestCount = nNodes;
                    try
                        if isfield(r.ctx.pipelineSpec, 'name') && ~isempty(r.ctx.pipelineSpec.name)
                            bestName = char(string(r.ctx.pipelineSpec.name));
                        end
                    catch
                    end
                    try
                        if isstruct(r.pipelineRef) && isfield(r.pipelineRef, 'path') && ~isempty(r.pipelineRef.path) && ...
                                ~isRunSnapshotPipelinePath(app, r.pipelineRef.path)
                            bestPath = char(string(r.pipelineRef.path));
                        end
                    catch
                    end
                end
            catch
                bestSpec = [];
            end
            if isempty(bestSpec)
                return;
            end
            if isempty(strtrim(bestName))
                bestName = defaultPipelineTemplateName(app);
            end
            pipeObj = pipeline('', bestName, 1);
            if ~isempty(strtrim(bestPath))
                pipeObj.setPath(bestPath, bestName);
            end
            pipeObj.nodes = bestSpec.nodes;
            if isfield(bestSpec, 'edges')
                pipeObj.edges = bestSpec.edges;
            end
            if isfield(bestSpec, 'branches')
                pipeObj.branches = bestSpec.branches;
            end
            pipeObj.description = 'Recovered from the widest pipelineSpec stored in project runs.';
        end

        function tf = shouldPreferHistoricalRunPipelineSpec(app, candidatePipe, historicalPipe, runObj)
            tf = false;
            if isempty(candidatePipe) || isempty(historicalPipe)
                return;
            end
            try
                if numel(historicalPipe.nodes) <= numel(candidatePipe.nodes)
                    return;
                end
                selectedIds = selectedNodeIdsFromRunObject(app, runObj);
                if isempty(selectedIds)
                    return;
                end
                candidateIds = cellstr(string({candidatePipe.nodes.id}));
                tf = numel(candidateIds) <= numel(selectedIds) && all(ismember(candidateIds, selectedIds));
            catch
                tf = false;
            end
        end

        function ids = selectedNodeIdsFromRunObject(app, runObj) %#ok<INUSD>
            ids = {};
            try
                if isstruct(runObj.ctx) && isfield(runObj.ctx, 'run') && isstruct(runObj.ctx.run) && ...
                        isfield(runObj.ctx.run, 'selectedNodes') && ~isempty(runObj.ctx.run.selectedNodes)
                    ids = cellstr(string(runObj.ctx.run.selectedNodes));
                end
            catch
                ids = {};
            end
        end

        function tf = sameRunTemplateForUi(app, runObj, templateId)
            tf = false;
            otherId = runTemplateIdForUi(app, runObj);
            if isempty(strtrim(templateId)) || isempty(strtrim(otherId))
                return;
            end
            tf = strcmpi(templateId, otherId);
        end

        function templateId = runTemplateIdForUi(app, runObj) %#ok<INUSD>
            templateId = '';
            try
                if isstruct(runObj.pipelineRef) && isfield(runObj.pipelineRef, 'id') && ~isempty(runObj.pipelineRef.id)
                    templateId = char(string(runObj.pipelineRef.id));
                elseif isprop(runObj, 'templateId') && ~isempty(runObj.templateId)
                    templateId = char(string(runObj.templateId));
                elseif isstruct(runObj.ctx) && isfield(runObj.ctx, 'pipelineRef') && isstruct(runObj.ctx.pipelineRef) && ...
                        isfield(runObj.ctx.pipelineRef, 'id') && ~isempty(runObj.ctx.pipelineRef.id)
                    templateId = char(string(runObj.ctx.pipelineRef.id));
                end
            catch
                templateId = '';
            end
        end

        function candidatePaths = expandRunPipelineTemplatePaths(app, candidatePaths)
            if isempty(candidatePaths)
                candidatePaths = {};
                return;
            end
            out = {};
            for i = 1:numel(candidatePaths)
                p = char(string(candidatePaths{i}));
                if isempty(strtrim(p))
                    continue;
                end
                out{end+1} = p; %#ok<AGROW>
                localPath = hubRemotePathToLocalPath(app, p);
                if ~isempty(localPath) && ~strcmp(localPath, p)
                    out{end+1} = localPath; %#ok<AGROW>
                end
            end
            candidatePaths = unique(out(~cellfun(@isempty, out)), 'stable');
        end

        function [bestPipe, msg] = loadBestPipelineTemplateCandidate(app, candidatePaths, runObj)
            bestPipe = [];
            msg = '';
            bestScore = -Inf;
            templateId = '';
            try
                if isstruct(runObj.pipelineRef) && isfield(runObj.pipelineRef, 'id') && ~isempty(runObj.pipelineRef.id)
                    templateId = char(string(runObj.pipelineRef.id));
                elseif isprop(runObj, 'templateId') && ~isempty(runObj.templateId)
                    templateId = char(string(runObj.templateId));
                end
            catch
                templateId = '';
            end

            for i = 1:numel(candidatePaths)
                candidate = char(string(candidatePaths{i}));
                reportPipelineOpenStage(app, 'Checking another pipeline template...', candidate);
                [pipe, loadMsg] = pipelineLoad(candidate);
                if isempty(pipe)
                    if ~isempty(loadMsg)
                        msg = loadMsg;
                    end
                    continue;
                end

                score = numel(pipe.nodes);
                if ~isRunSnapshotPipelinePath(app, candidate)
                    score = score + 10000;
                end
                try
                    if ~isempty(templateId) && strcmpi(char(string(pipe.strid)), templateId)
                        score = score + 1000;
                    end
                catch
                end
                if score > bestScore
                    bestScore = score;
                    bestPipe = pipe;
                end
            end
        end

        function tf = isRunSnapshotPipelinePath(app, pathValue) %#ok<INUSD>
            txt = lower(strrep(char(string(pathValue)), '\', '/'));
            tf = contains(txt, '/pipeline_runs/') || ...
                contains(txt, '/hub_pipeline_bundle/') || ...
                contains(txt, '/pipeline_run_');
        end

        function paths = projectPipelineTemplatePathsForRun(app, runObj, discoverFolders) %#ok<INUSD>
            if nargin < 3, discoverFolders = true; end
            paths = {};
            shallowObj = app.CurrentProject;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end

            configuredPath = '';
            try
                if isprop(shallowObj, 'runProfiles') && isfield(shallowObj.runProfiles, 'pipeline') && ...
                        isstruct(shallowObj.runProfiles.pipeline) && isfield(shallowObj.runProfiles.pipeline, 'defaultTemplatePath')
                    configuredPath = char(string(shallowObj.runProfiles.pipeline.defaultTemplatePath));
                end
            catch
                configuredPath = '';
            end
            if ~isempty(strtrim(configuredPath))
                paths{end+1} = configuredPath; %#ok<AGROW>
            end

            projectRoot = '';
            try
                if ~isempty(shallowObj.io.path) && ~isempty(shallowObj.io.file)
                    projectRoot = fullfile(char(string(shallowObj.io.path)), char(string(shallowObj.io.file)));
                end
            catch
                projectRoot = '';
            end
            if isempty(projectRoot)
                return;
            end

            templateId = '';
            try
                if isstruct(runObj.pipelineRef) && isfield(runObj.pipelineRef, 'id') && ~isempty(runObj.pipelineRef.id)
                    templateId = char(string(runObj.pipelineRef.id));
                elseif isprop(runObj, 'templateId') && ~isempty(runObj.templateId)
                    templateId = char(string(runObj.templateId));
                end
            catch
                templateId = '';
            end
            if ~isempty(strtrim(templateId))
                paths{end+1} = fullfile(projectRoot, templateId, 'pipeline.json'); %#ok<AGROW>
            end

            paths{end+1} = fullfile(projectRoot, 'pipeline.json'); %#ok<AGROW>
            if ~discoverFolders
                return;
            end
            reportPipelineOpenStage(app, 'Searching the project folder for its pipeline template...', projectRoot);
            if exist(projectRoot, 'dir') ~= 7
                return;
            end
            d = dir(fullfile(projectRoot, '*', 'pipeline.json'));
            for i = 1:numel(d)
                paths{end+1} = fullfile(d(i).folder, d(i).name); %#ok<AGROW>
            end
        end

        function paths = classifierPipelineTemplatePathsForRun(app, runObj)
            paths = {};
            templateId = runTemplateIdForUi(app, runObj);
            if isempty(strtrim(templateId))
                return;
            end
            classiPath = '';
            try
                if isstruct(runObj.targetRef) && isfield(runObj.targetRef, 'classiPath')
                    classiPath = char(string(runObj.targetRef.classiPath));
                end
            catch
            end
            if isempty(strtrim(classiPath))
                try
                    classiPath = fileparts(fileparts(char(string(runObj.path))));
                catch
                    classiPath = '';
                end
            end
            if isempty(strtrim(classiPath))
                return;
            end
            root = classiPath;
            for level = 1:4
                paths{end+1} = fullfile(root, 'pipeline_templates', templateId, 'pipeline.json'); %#ok<AGROW>
                paths{end+1} = fullfile(root, 'pipeline', templateId, 'pipeline.json'); %#ok<AGROW>
                paths{end+1} = fullfile(root, 'pipeline', templateId, templateId, 'pipeline.json'); %#ok<AGROW>
                paths{end+1} = fullfile(root, templateId, 'pipeline.json'); %#ok<AGROW>
                parent = fileparts(root);
                if isempty(parent) || strcmp(parent, root)
                    break;
                end
                root = parent;
            end
        end

        function paths = recentPipelineTemplatePathsForRun(app, runObj) %#ok<INUSD>
            paths = {};
            templateId = runTemplateIdForUi(app, runObj);
            if isempty(strtrim(templateId))
                return;
            end
            try
                recent = getpref('DetecDiv', 'pipeline2RecentPipelines', {});
                recent = cellstr(string(recent(:)));
            catch
                recent = {};
            end
            for i = 1:numel(recent)
                candidate = char(string(recent{i}));
                try
                    reportPipelineOpenStage(app, 'Checking a recent pipeline template...', candidate);
                    [pipeObj, ~] = pipelineLoad(candidate);
                    if ~isempty(pipeObj) && isa(pipeObj, 'pipeline') && strcmpi(char(string(pipeObj.strid)), templateId)
                        paths{end+1} = candidate; %#ok<AGROW>
                    end
                catch
                end
            end
        end

        function localPath = hubRemotePathToLocalPath(app, remotePath)
            localPath = '';
            remotePath = char(string(remotePath));
            if isempty(strtrim(remotePath))
                return;
            end
            try
                hub = detecdiv_hub_settings_get();
            catch
                hub = struct();
            end
            mappings = struct('remoteRoot', {}, 'localRoot', {});
            try
                mappings = getHubPathMappings(app, hub);
            catch
            end
            try
                if isfield(hub, 'defaultRemoteProjectRoot') && isfield(hub, 'defaultLocalProjectRoot') && ...
                        ~isempty(hub.defaultRemoteProjectRoot) && ~isempty(hub.defaultLocalProjectRoot)
                    mappings(end+1).remoteRoot = char(string(hub.defaultRemoteProjectRoot)); %#ok<AGROW>
                    mappings(end).localRoot = char(string(hub.defaultLocalProjectRoot));
                end
            catch
            end
            remoteComparable = regexprep(strrep(remotePath, '\', '/'), '[\/]+$', '');
            bestLen = 0;
            bestLocalRoot = '';
            bestSuffix = '';
            for i = 1:numel(mappings)
                try
                    remoteRoot = regexprep(strrep(char(string(mappings(i).remoteRoot)), '\', '/'), '[\/]+$', '');
                    localRoot = regexprep(strrep(char(string(mappings(i).localRoot)), '/', filesep), '[\\\/]+$', '');
                    if isempty(remoteRoot) || isempty(localRoot)
                        continue;
                    end
                    if startsWith(remoteComparable, remoteRoot) && ...
                            (numel(remoteComparable) == numel(remoteRoot) || any(remoteComparable(numel(remoteRoot)+1) == ['/' '\']))
                        if numel(remoteRoot) > bestLen
                            bestLen = numel(remoteRoot);
                            bestLocalRoot = localRoot;
                            bestSuffix = remoteComparable(numel(remoteRoot)+1:end);
                        end
                    end
                catch
                end
            end
            if bestLen == 0
                return;
            end
            bestSuffix = regexprep(bestSuffix, '^[\/\\]+', '');
            if isempty(bestSuffix)
                localPath = bestLocalRoot;
            else
                localPath = fullfile(bestLocalRoot, strrep(bestSuffix, '/', filesep));
            end
        end

        function value = getNestedRunField(app, runObj, path, defaultValue) %#ok<INUSD>
            value = defaultValue;
            try
                cur = runObj;
                for i = 1:numel(path)
                    key = path{i};
                    if isa(cur, 'pipelineRun') && isprop(cur, key)
                        cur = cur.(key);
                    elseif isstruct(cur) && isfield(cur, key)
                        cur = cur.(key);
                    else
                        return;
                    end
                end
                if ~isempty(cur)
                    value = cur;
                end
            catch
                value = defaultValue;
            end
        end

        function varName = findWorkspaceVarForObject(app, obj, fallback) %#ok<INUSD>
            varName = '';
            try
                vars = evalin('base', 'who');
            catch
                vars = {};
            end
            for i = 1:numel(vars)
                try
                    cand = evalin('base', vars{i});
                    if isequal(cand, obj)
                        varName = vars{i};
                        return;
                    end
                catch
                end
            end
            if nargin >= 3 && ~isempty(fallback)
                varName = matlab.lang.makeValidName(char(string(fallback)));
            else
                varName = 'detecdivObject';
            end
            try
                assignin('base', varName, obj);
            catch
            end
        end

        function configureControls(app)
            configureFileMenus(app);
            configureGraphAppearance(app);
            app.TypeDropDown.Items = {'dataLoader','ROI definition','roiExtract','processor','classifier','custom'};
            app.TypeDropDown.Value = 'dataLoader';
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            updateSubtypeChoices(app);
            app.SubtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SubtypeDropDownValueChanged, true);
            configureRuntimeTabs(app);
            app.AdvancedmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedmodeCheckBoxValueChanged, true);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);

            app.UIWorkspacePipelineTable.ColumnName = {'Module','Type','Package','Description'};
            app.UIWorkspacePipelineTable.ColumnEditable = false(1,4);
            app.UIWorkspacePipelineTable.ColumnWidth = {82 82 62 'auto'};
            app.UIWorkspacePipelineTable.Selection = [];
            app.UIWorkspacePipelineTable.SelectionChangedFcn = createCallbackFcn(app, @UIWorkspacePipelineTableSelectionChanged, true);

            app.UISelectedModuleTable.ColumnName = {'Run','Module','Type','Package'};
            app.UISelectedModuleTable.ColumnEditable = [true false false false];
            app.UISelectedModuleTable.ColumnWidth = {52 250 110 'auto'};
            app.UISelectedModuleTable.CellEditCallback = @(src,event)selectedModuleTableEdited(app, src, event);

            app.ResumeoptionsDropDown.Items = {'Resume previous progress','Restart from scratch'};
            app.ResumeoptionsDropDown.Value = 'Resume previous progress';
            app.ResumeoptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @ResumeoptionsDropDownValueChanged, true);
            app.ExecutionDropDown.Items = {'Auto','GPU','CPU'};
            app.ExecutionDropDown.Value = 'Auto';
            buildHubRuntimeControls(app);
            buildRunArtifactControls(app);
            buildRuntimeControls(app);
            configureMonitorControls(app);

            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.CheckbuildButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpipelineButtonPushed, true);
            app.SmokeTestButton.ButtonPushedFcn = createCallbackFcn(app, @SmokeTestButtonPushed, true);
            try, app.NewRunButton.ButtonPushedFcn = createCallbackFcn(app, @NewRunButtonPushed, true); catch, end
            app.NewpipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @NewpipelineMenuSelected, true);
            app.LoadpipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadpipelineMenuSelected, true);
            updateRecentPipelinesMenu(app);
            app.SavecurrentpipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecurrentpipelineMenuSelected, true);
            app.SavepipelineasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavepipelineasMenuSelected, true);
            app.LoadrunMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadrunMenuSelected, true);
            app.SaverunMenu.MenuSelectedFcn = createCallbackFcn(app, @SaverunMenuSelected, true);
            app.SaverunasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaverunasMenuSelected, true);
            app.ExportpipelineMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportpipelineMenuSelected, true);
            app.GenerateDocumentationMenu = uimenu(app.FileMenu, ...
                'Text', 'Generate pipeline documentation PDF...', 'Separator', 'on', ...
                'MenuSelectedFcn', @(~,~)generatePipelineDocumentationPdf(app));
            app.OpenDocumentationMenu = uimenu(app.FileMenu, ...
                'Text', 'Open pipeline documentation PDF', ...
                'MenuSelectedFcn', @(~,~)openPipelineDocumentationPdf(app));
            uimenu(app.FileMenu, 'Text', 'Open current run folder', 'Separator', 'on', ...
                'MenuSelectedFcn', @(~,~)openCurrentRunArtifact(app, 'folder'));
            uimenu(app.FileMenu, 'Text', 'Open current run log', ...
                'MenuSelectedFcn', @(~,~)showCurrentRunLog(app));
            uimenu(app.FileMenu, 'Text', 'Review current run', ...
                'MenuSelectedFcn', @(~,~)showCurrentRunReview(app));
            uimenu(app.FileMenu, 'Text', 'Open current run params', ...
                'MenuSelectedFcn', @(~,~)openCurrentRunArtifact(app, 'params'));
            uimenu(app.FileMenu, 'Text', 'Refresh input inventory', ...
                'MenuSelectedFcn', @(~,~)refreshInputInventory(app));
            rebuildModulesMenu(app);
            updateDocumentationMenuState(app);

            setRuntimeStatus(app, sprintf('Template mode: runtime locked.\nClick New Run to configure execution.'));
            app.PipelineandRuncheckreportLabel.WordWrap = 'on';
            app.PipelineandRuncheckreportLabel.VerticalAlignment = 'top';
            app.PipelineandRuncheckreportLabel.Text = 'No pipeline error.';

            app.ModuleContextMenu = uicontextmenu(app.UIFigure);
            app.GraphContextMenu = uicontextmenu(app.UIFigure);
            rebuildModuleContextMenus(app);
            try
                app.UIGraphAxes.ContextMenu = app.GraphContextMenu;
            catch
                try, app.UIGraphAxes.UIContextMenu = app.GraphContextMenu; catch, end
            end
            app.UIGraphAxes.ButtonDownFcn = createCallbackFcn(app, @GraphBackgroundButtonDown, true);
            app.GraphPanel.ButtonDownFcn = createCallbackFcn(app, @GraphBackgroundButtonDown, true);

            app.IdEditField.ValueChangedFcn = createCallbackFcn(app, @IdEditFieldValueChanged, true);
            updateCommonControlsEnableState(app);
            setRuntimeModeUnlocked(app, false);
        end

        function configureRuntimeTabs(app)
            % The Build tab group is now reserved for per-module editors.
            % App Designer keeps one placeholder tab so the group can be
            % laid out visually; remove that placeholder at startup.
            try
                if ~isempty(app.RuntimeInputsTab) && isvalid(app.RuntimeInputsTab)
                    delete(app.RuntimeInputsTab);
                end
            catch
            end
        end

        function reorderRuntimeTabs(app)
            % Module tabs are created in pipeline order; no runtime input
            % or option tabs are mixed into this group anymore.
        end

        function configureGraphAppearance(app)
            app.UIGraphAxes.XTick = [];
            app.UIGraphAxes.YTick = [];
            app.UIGraphAxes.Box = 'on';
            app.UIGraphAxes.Toolbar.Visible = 'off';
            title(app.UIGraphAxes, '');
            xlabel(app.UIGraphAxes, '');
            ylabel(app.UIGraphAxes, '');
            zlabel(app.UIGraphAxes, '');
        end

        function configureFileMenus(app)
            app.LoadpipelineMenu = uimenu(app.FileMenu, ...
                'Text', 'Load pipeline...');
            app.LoadrecentpipelineMenu = uimenu(app.FileMenu, ...
                'Text', 'Load recent pipeline');
            app.LoadrunMenu = uimenu(app.FileMenu, ...
                'Text', 'Load run...', 'Separator', 'on');
            app.ModulesMenu = uimenu(app.UIFigure, 'Text', '+');
        end

        function configureMonitorControls(app)
            app.ConsoleTextArea.Editable = 'off';
            app.ConsoleTextArea.FontName = 'Consolas';
            app.ConsoleTextArea.Value = {'Monitor ready.'};
            app.CancelrunButton.ButtonPushedFcn = @(~,~)requestActiveRunCancellation(app);
            app.CancelrunButton.Enable = 'off';
            app.ProgressionStatusLabel.Text = 'Idle';
            app.MonitorProgressGauge = uigauge(app.MonitorTab, 'linear', ...
                'Limits', [0 1], ...
                'Value', 0, ...
                'MajorTicks', [0 0.25 0.5 0.75 1], ...
                'MajorTickLabels', {'0%','25%','50%','75%','100%'}, ...
                'Position', [91 869 1111 36]);
            app.MonitorLastProgress = 0;
            app.MonitorLastProgressConsoleSignature = '';
            app.MonitorLastHubConsoleText = '';
            app.MonitorHasModuleProgress = false;
            app.MonitorRunStartTic = [];
            app.MonitorEtaSeconds = NaN;
        end

        function modules = defaultModuleLibrary(app, forceRefresh)
            if nargin < 2, forceRefresh = false; end
            persistent cachedKey cachedModules cachedItems cachedDiagnostics
            root = repoRoot(app);
            if ispc
                preferenceFile = fullfile(prefdir, 'Detecdiv', 'userprefs.mat');
            else
                preferenceFile = fullfile(getenv('HOME'), 'Detecdiv', 'userprefs.mat');
            end
            stamp = dir(preferenceFile);
            stampText = '';
            if ~isempty(stamp), stampText = sprintf('%.15g:%d', stamp.datenum, stamp.bytes); end
            key = jsonencode({root, path, app.ModuleCataloguePackagePaths, stampText});
            if ~forceRefresh && isequal(cachedKey, key)
                reportPipelineOpenStage(app, 'Using the cached module catalogue...');
                modules = cachedModules;
                app.ModuleCatalogueItems = cachedItems;
                app.ModuleCatalogueDiagnostics = cachedDiagnostics;
                app.ModuleCatalogueFromCache = true;
                return;
            end
            [modules, app.ModuleCatalogueItems, app.ModuleCatalogueDiagnostics] = ...
                detecdiv_modules_ui('Root', root, ...
                'PackagePaths', app.ModuleCataloguePackagePaths);
            modules(end+1,:) = {'Custom function', 'custom', 'customModule', ...
                'Call a user MATLAB function with declared inputs, outputs and parameters'};
            modules(end+1,:) = customPackageModuleRow(app);
            cachedKey = key;
            cachedModules = modules;
            cachedItems = app.ModuleCatalogueItems;
            cachedDiagnostics = app.ModuleCatalogueDiagnostics;
            app.ModuleCatalogueFromCache = false;
        end

        function rows = customPackageModuleRow(app) %#ok<INUSD>
            rows = {'<custom pkg>', 'customPackage', '', 'Load an external processor/classifier package'};
        end

        function rootDir = repoRoot(app) %#ok<INUSD>
            rootDir = pwd;
            try
                appPath = which('pipeline2');
                if ~isempty(appPath)
                    candidate = fileparts(fileparts(fileparts(appPath)));
                    if isfolder(fullfile(candidate, 'engine')) && isfolder(fullfile(candidate, 'structure'))
                        rootDir = candidate;
                    end
                end
            catch
            end
        end

        function refreshAvailableModuleTable(app)
            app.UIWorkspacePipelineTable.Data = app.AvailableModules;
        end

        function tf = moduleCatalogueRowCanAdd(app, row, verifyPath)
            if nargin < 3, verifyPath = true; end
            tf = false;
            if row < 1 || row > size(app.AvailableModules, 1)
                return;
            end
            if row > numel(app.ModuleCatalogueItems)
                tf = any(strcmpi(app.AvailableModules{row,2}, {'custom','customPackage'}));
                return;
            end
            item = app.ModuleCatalogueItems(row);
            if ~item.canAdd
                return;
            end
            if app.OpeningPipeline || ~verifyPath
                % Menu construction repeats this check for every module in
                % several menus. Discovery already checked the entrypoints;
                % recheck live paths when the user actually selects a module.
                tf = true;
                return;
            end
            resolved = which(item.module.entrypoint);
            if isempty(resolved) || ...
                    ~sameModuleCataloguePath(app, fileparts(resolved), item.module.path)
                return;
            end
            entrypoints = struct2cell(item.module.entrypoints);
            for i = 1:numel(entrypoints)
                if isempty(entrypoints{i}), continue; end
                resolved = which(entrypoints{i});
                if isempty(resolved) || ...
                        ~sameModuleCataloguePath(app, fileparts(resolved), item.module.path)
                    return;
                end
            end
            tf = true;
        end

        function showModuleCatalogueDetails(app, row)
            if row <= numel(app.ModuleCatalogueItems)
                item = app.ModuleCatalogueItems(row);
                details = item.details;
                if ~moduleCatalogueRowCanAdd(app, row) && ~isempty(item.reason)
                    details = [details(:); {item.reason}];
                end
                setRuntimeStatus(app, strjoin(details, ' | '));
                app.UIWorkspacePipelineTable.Tooltip = strjoin(details, newline);
            else
                summary = char(string(app.AvailableModules{row,4}));
                setRuntimeStatus(app, summary);
                app.UIWorkspacePipelineTable.Tooltip = summary;
            end
        end

        function tf = moduleCatalogueAllowsMutation(app, nodeType, pkg, paramsPatch)
            % This gate is used only when adding or changing a module. Saved
            % nodes keep their existing packages, links and parameter data.
            tf = false;
            if isstruct(paramsPatch) && ...
                    ((isfield(paramsPatch, 'customPackageDir') && ~isempty(paramsPatch.customPackageDir)) || ...
                    (isfield(paramsPatch, 'customPackageRoot') && ~isempty(paramsPatch.customPackageRoot)))
                tf = true; % The user explicitly selected this package.
                return;
            end
            if strcmpi(nodeType, 'custom') && strcmpi(pkg, 'customModule')
                tf = true;
                return;
            end
            % The legacy type controls intentionally serialize an empty pkg
            % for built-in data/ROI nodes. Normalize only this comparison.
            comparisonType = char(string(nodeType));
            comparisonPkg = char(string(pkg));
            if strcmpi(comparisonType, 'roiIdentify')
                comparisonType = 'roiPattern';
            end
            if isempty(comparisonPkg) && any(strcmpi(comparisonType, ...
                    {'dataLoader','roiPattern','roiManual','roiGrid','roiTracked','roiExtract'}))
                comparisonPkg = comparisonType;
            end
            reason = 'This module is unavailable or ambiguous in the current catalogue.';
            for row = 1:numel(app.ModuleCatalogueItems)
                if ~strcmpi(app.AvailableModules{row,2}, comparisonType) || ...
                        ~strcmpi(app.AvailableModules{row,3}, comparisonPkg)
                    continue;
                end
                if moduleCatalogueRowCanAdd(app, row)
                    tf = true;
                    return;
                end
                item = app.ModuleCatalogueItems(row);
                if ~isempty(item.reason)
                    reason = item.reason;
                else
                    reason = 'The active MATLAB package differs from the catalogue copy. Refresh the catalogue before adding it.';
                end
            end
            message = sprintf('%s (%s): %s', char(string(pkg)), char(string(nodeType)), reason);
            setRuntimeStatus(app, message);
            uialert(app.UIFigure, message, 'Module unavailable', 'Icon', 'warning');
        end

        function tf = sameModuleCataloguePath(app, first, second) %#ok<INUSD>
            first = char(java.io.File(char(string(first))).getCanonicalPath());
            second = char(java.io.File(char(string(second))).getCanonicalPath());
            if ispc
                tf = strcmpi(first, second);
            else
                tf = strcmp(first, second);
            end
        end

        function refreshSelectedModuleTable(app, preservePrevious)
            if nargin < 2
                preservePrevious = true;
            end
            nodes = app.Data.nodes;
            if preservePrevious
                previous = currentSelectedRunMap(app);
            else
                previous = containers.Map('KeyType', 'char', 'ValueType', 'logical');
            end
            data = cell(numel(nodes), 4);
            for i = 1:numel(nodes)
                nodeId = char(string(getField(app, nodes(i), 'id', '')));
                if isKey(previous, nodeId)
                    data{i,1} = previous(nodeId);
                else
                    data{i,1} = true;
                end
                data{i,2} = nodeId;
                data{i,3} = char(string(getField(app, nodes(i), 'type', '')));
                data{i,4} = char(string(getField(app, nodes(i), 'pkg', '')));
            end
            app.UISelectedModuleTable.Data = data;
            refreshSelectedModuleTableStyles(app);
        end

        function refreshSelectedModuleTableStyles(app)
            try
                removeStyle(app.UISelectedModuleTable);
                data = app.UISelectedModuleTable.Data;
                inactiveRows = [];
                for i = 1:size(data, 1)
                    if ~logical(data{i,1})
                        inactiveRows(end+1) = i; %#ok<AGROW>
                    end
                end
                if ~isempty(inactiveRows)
                    inactiveStyle = uistyle('BackgroundColor', [0.94 0.94 0.94], ...
                        'FontColor', [0.50 0.50 0.50]);
                    addStyle(app.UISelectedModuleTable, inactiveStyle, 'row', inactiveRows);
                end
            catch
            end
        end

        function map = currentSelectedRunMap(app)
            map = containers.Map('KeyType', 'char', 'ValueType', 'logical');
            try
                data = app.UISelectedModuleTable.Data;
                for i = 1:size(data, 1)
                    nodeId = char(string(data{i,2}));
                    if isempty(nodeId)
                        continue;
                    end
                    include = true;
                    try
                        include = logical(data{i,1});
                    catch
                    end
                    map(nodeId) = include;
                end
            catch
            end
        end

        function selectedModuleTableEdited(app, table, event)
            try
                if nargin >= 3 && ~isempty(event) && ~isempty(event.Indices)
                    data = table.Data;
                    data{event.Indices(1), event.Indices(2)} = logical(event.NewData);
                    table.Data = data;
                    refreshSelectedModuleTableStyles(app);
                    drawnow limitrate nocallbacks;
                end
            catch
            end
            markRunDirty(app, true);
            d = openRuntimeProgress(app, 'Runtime modules', 'Updating selected modules...');
            cleanupObj = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
            updateRuntimeProgress(app, d, 'Redrawing pipeline graph...');
            redrawGraph(app);
            updateRuntimeProgress(app, d, 'Updating module tab states...');
            refreshModuleTabActiveStates(app);
            updateRuntimeProgress(app, d, 'Checking pipeline...');
            refreshValidationReport(app, false);
            redrawGraph(app);
        end

        function tf = isRawPrepNode(app, node) %#ok<INUSD>
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            tf = any(strcmp(nodeType, {'dataloader','roipattern','roiidentify','roimanual','roigrid','roitracked','roiextract'}));
        end

        function tf = runtimeStartsFromExistingProject(app)
            mode = getRuntimeValue(app, 'inputSourceMode');
            if isempty(mode)
                mode = 'existing_rois';
            end
            tf = strcmpi(char(string(mode)), 'existing_rois');
        end

        function tf = runtimeStartsFromClassifier(app)
            mode = getRuntimeValue(app, 'inputSourceMode');
            tf = strcmpi(char(string(mode)), 'classifier_rois');
        end

        function tf = runtimeProjectPathUsable(app, projectPath, allowCreate) %#ok<INUSD>
            projectPath = strtrim(char(string(projectPath)));
            tf = false;
            if isempty(projectPath)
                return;
            end
            if exist(projectPath, 'dir') == 7 || exist(projectPath, 'file') == 2
                tf = true;
                return;
            end
            if nargin < 3 || ~allowCreate
                return;
            end
            [parentFolder, ~, ext] = fileparts(projectPath);
            if strcmpi(ext, '.mat')
                targetFolder = parentFolder;
                parentFolder = fileparts(targetFolder);
            else
                targetFolder = projectPath; %#ok<NASGU>
            end
            tf = ~isempty(parentFolder) && exist(parentFolder, 'dir') == 7;
        end

        function tf = hasLoadedRuntimeProject(app)
            tf = ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow');
        end

        function IdEditFieldValueChanged(app, event) %#ok<INUSD>
            if isnan(app.SelectedNodeIndex) || app.SelectedNodeIndex < 1 || app.SelectedNodeIndex > numel(app.Data.nodes)
                return;
            end
            oldId = char(string(app.Data.nodes(app.SelectedNodeIndex).id));
            newId = matlab.lang.makeValidName(strtrim(char(string(app.IdEditField.Value))));
            if isempty(newId)
                newId = oldId;
            end
            ids = {app.Data.nodes.id};
            otherIdx = setdiff(1:numel(ids), app.SelectedNodeIndex);
            if any(strcmp(ids(otherIdx), newId))
                newId = makeUniqueNodeId(app, newId);
            end
            app.IdEditField.Value = newId;
            app.Data.nodes(app.SelectedNodeIndex).id = newId;
            app.Data.nodes(app.SelectedNodeIndex).name = newId;
            app.Data.edges = replaceNodeIdInEdges(app, app.Data.edges, oldId, newId);
            renameRuntimeNodeParams(app, oldId, newId);
            renameSymbolicBindingReferences(app, oldId, newId);
            refreshSelectedModuleTable(app);
            refreshModuleTabs(app);
            refreshValidationReport(app, false);
            redrawGraph(app);
            updateCommonControlsEnableState(app);
            markPipelineDirty(app, true);
        end

        function TypeDropDownValueChanged(app, event) %#ok<INUSD>
            updateSubtypeChoices(app);
            applyTypeControlsToSelectedNode(app);
        end

        function SubtypeDropDownValueChanged(app, event) %#ok<INUSD>
            applyTypeControlsToSelectedNode(app);
        end

        function AdvancedmodeCheckBoxValueChanged(app, event) %#ok<INUSD>
            % Deprecated: module parameter visibility is now determined by
            % parameter role (bindings/runtime/static), not by an advanced
            % toggle.
        end

        function ResumeoptionsDropDownValueChanged(app, event) %#ok<INUSD>
            applyRecommendedOutputPolicyForResume(app);
            updateRuntimeInputStates(app);
            refreshValidationReport(app);
        end

        function TabGroupSelectionChanged(app, event)
            if app.IsRefreshingTabs
                return;
            end
            tab = event.NewValue;
            if isempty(tab) || ~isvalid(tab) || ~isstruct(tab.UserData) || ~isfield(tab.UserData, 'nodeId')
                return;
            end
            ids = {app.Data.nodes.id};
            idx = find(strcmp(ids, char(string(tab.UserData.nodeId))), 1);
            if isempty(idx) || isequal(idx, app.SelectedNodeIndex)
                return;
            end
            selectNode(app, idx);
        end

        function applyTypeControlsToSelectedNode(app)
            if isnan(app.SelectedNodeIndex) || app.SelectedNodeIndex < 1 || app.SelectedNodeIndex > numel(app.Data.nodes)
                return;
            end
            [nodeType, pkg] = selectedModuleTypeAndPackage(app);
            node = app.Data.nodes(app.SelectedNodeIndex);
            if strcmp(char(string(getField(app, node, 'type', ''))), nodeType) && strcmp(char(string(getField(app, node, 'pkg', ''))), pkg)
                return;
            end
            if ~moduleCatalogueAllowsMutation(app, nodeType, pkg, struct())
                selectTypeControlsForNode(app, node);
                return;
            end
            changeSelectedModuleType(app,nodeType,pkg);
        end

        function updateSubtypeChoices(app)
            typeLabel = char(string(app.TypeDropDown.Value));
            switch lower(typeLabel)
                case 'roi definition'
                    items = moduleLibraryPackagesForType(app, {'roiPattern','roiManual','roiGrid','roiTracked'});
                case 'processor'
                    items = moduleLibraryPackagesForType(app, {'processor'});
                case 'classifier'
                    items = moduleLibraryPackagesForType(app, {'classifier'});
                case 'custom'
                    items = {'customModule'};
                otherwise
                    items = {typeLabel};
            end
            if isempty(items)
                items = {typeLabel};
            end
            app.SubtypeDropDown.Items = items;
            app.SubtypeDropDown.Value = items{1};
        end

        function items = moduleLibraryPackagesForType(app, types)
            items = {};
            if isempty(app.AvailableModules)
                return;
            end
            types = cellstr(string(types(:)));
            for i = 1:size(app.AvailableModules, 1)
                if ~moduleCatalogueRowCanAdd(app, i)
                    continue;
                end
                nodeType = char(string(app.AvailableModules{i,2}));
                pkg = char(string(app.AvailableModules{i,3}));
                if ~any(strcmpi(types, nodeType))
                    continue;
                end
                if strcmpi(nodeType, 'processor') || strcmpi(nodeType, 'classifier')
                    if ~isempty(pkg)
                        items{end+1} = pkg; %#ok<AGROW>
                    end
                elseif strcmpi(nodeType, 'dataLoader')
                    if ~isempty(pkg)
                        items{end+1} = pkg; %#ok<AGROW>
                    else
                        items{end+1} = nodeType; %#ok<AGROW>
                    end
                else
                    items{end+1} = nodeType; %#ok<AGROW>
                end
            end
            items = unique(items(~cellfun(@isempty, items)), 'stable');
        end

        function UIWorkspacePipelineTableSelectionChanged(app, event) %#ok<INUSD>
            sel = app.UIWorkspacePipelineTable.Selection;
            if isempty(sel)
                return;
            end
            row = sel(1,1);
            if row < 1 || row > size(app.AvailableModules, 1)
                return;
            end
            showModuleCatalogueDetails(app, row);
            if ~moduleCatalogueRowCanAdd(app, row)
                return;
            end
            moduleType = app.AvailableModules{row,2};
            pkg = app.AvailableModules{row,3};
            if strcmpi(moduleType, 'dataLoader')
                app.TypeDropDown.Value = 'dataLoader';
                updateSubtypeChoices(app);
                if any(strcmp(app.SubtypeDropDown.Items, pkg))
                    app.SubtypeDropDown.Value = pkg;
                end
            elseif any(strcmp(moduleType, {'roiPattern','roiManual','roiGrid','roiTracked'}))
                app.TypeDropDown.Value = 'ROI definition';
                updateSubtypeChoices(app);
                app.SubtypeDropDown.Value = moduleType;
            elseif strcmp(moduleType, 'processor')
                app.TypeDropDown.Value = 'processor';
                updateSubtypeChoices(app);
                if any(strcmp(app.SubtypeDropDown.Items, pkg))
                    app.SubtypeDropDown.Value = pkg;
                end
            elseif strcmp(moduleType, 'classifier')
                app.TypeDropDown.Value = 'classifier';
                updateSubtypeChoices(app);
                if any(strcmp(app.SubtypeDropDown.Items, pkg))
                    app.SubtypeDropDown.Value = pkg;
                end
            elseif strcmpi(moduleType, 'customPackage')
                setRuntimeStatus(app, 'Add an external package using the module library or Modules menu.');
                return;
            else
                app.TypeDropDown.Value = moduleType;
                updateSubtypeChoices(app);
            end
            showModuleCatalogueDetails(app, row);
        end

        function changeSelectedModuleType(app, nodeType, pkg, paramsPatch)
            if nargin < 4
                paramsPatch = struct();
            end
            if isnan(app.SelectedNodeIndex) || app.SelectedNodeIndex < 1 || app.SelectedNodeIndex > numel(app.Data.nodes)
                return;
            end
            node = app.Data.nodes(app.SelectedNodeIndex);
            if strcmp(char(string(getField(app, node, 'type', ''))), nodeType) && strcmp(char(string(getField(app, node, 'pkg', ''))), pkg)
                return;
            end
            if ~moduleCatalogueAllowsMutation(app, nodeType, pkg, paramsPatch)
                return;
            end
            node = clearModuleDerivedFields(app, node);
            node.type = nodeType;
            node.pkg = pkg;
            node.func = defaultNodeFunction(app, nodeType, pkg);
            node.gui = defaultNodeGui(app, nodeType, pkg);
            node.params = applyRuntimeDefaultsToParams(app, nodeType, defaultNodeParams(app, nodeType, pkg));
            node.params = mergeStructOverride(app, node.params, paramsPatch);
            node = applyCustomPackagePatchToNode(app, node, paramsPatch);
            node = pipelineNormalizeNodes(node, 'persist');
            [nodesAligned, node] = alignStructFieldsForAppend(app, app.Data.nodes, node);
            nodesAligned(app.SelectedNodeIndex) = node;
            app.Data.nodes = pipelineNormalizeNodes(nodesAligned, 'persist');
            % A type replacement keeps explicit topology, ports and bindings.
            % Contract validation reports links that require reconnection.
            refreshAfterModelChange(app);
        end

        function node = clearModuleDerivedFields(app, node) %#ok<INUSD>
            staleFields = {'paramRequired','requiredParams','contract','inputs','outputs'};
            for i = 1:numel(staleFields)
                if isfield(node, staleFields{i})
                    node = rmfield(node, staleFields{i});
                end
            end
        end

        function addModuleFromCurrentSelection(app)
            choice = chooseModuleFromLibrary(app, 'Add module');
            if isempty(choice)
                return;
            end
            nodeType = choice.type;
            pkg = choice.pkg;
            paramsPatch = getField(app, choice, 'paramsPatch', struct());
            addModuleOfType(app, nodeType, pkg, paramsPatch);
        end

        function addModuleOfType(app, nodeType, pkg, paramsPatch)
            if nargin < 4
                paramsPatch = struct();
            end
            if ~moduleCatalogueAllowsMutation(app, nodeType, pkg, paramsPatch)
                return;
            end
            app.NodeCounter = app.NodeCounter + 1;
            node = makePipelineNode(app, nodeType, pkg, app.NodeCounter);
            node.params = mergeStructOverride(app, node.params, paramsPatch);
            node = applyCustomPackagePatchToNode(app, node, paramsPatch);
            node = pipelineNormalizeNodes(node, 'persist');

            if isempty(app.Data.nodes)
                node.layout = [1 1 1 1];
            else
                maxCol = max(arrayfun(@(n) getLayoutCol(app, n), app.Data.nodes));
                node.layout = [maxCol + 1 1 1 1];
            end

            app.Data.nodes = appendStruct(app, app.Data.nodes, node);
            app.SelectedNodeIndex = numel(app.Data.nodes);
            rebuildEdgesFromLayout(app);
            refreshAfterModelChange(app);
        end

        function addModuleAfterSelected(app, nodeType, pkg, paramsPatch)
            if nargin < 4
                paramsPatch = struct();
            end
            if ~moduleCatalogueAllowsMutation(app, nodeType, pkg, paramsPatch)
                return;
            end
            if isnan(app.SelectedNodeIndex) || app.SelectedNodeIndex < 1 || app.SelectedNodeIndex > numel(app.Data.nodes)
                addModuleOfType(app, nodeType, pkg, paramsPatch);
                return;
            end
            targetCol = getLayoutCol(app, app.Data.nodes(app.SelectedNodeIndex)) + 1;
            targetRow = getLayoutRow(app, app.Data.nodes(app.SelectedNodeIndex));
            shiftLayoutRowFromColumn(app, targetRow, targetCol);
            app.NodeCounter = app.NodeCounter + 1;
            node = makePipelineNode(app, nodeType, pkg, app.NodeCounter);
            node.params = mergeStructOverride(app, node.params, paramsPatch);
            node = applyCustomPackagePatchToNode(app, node, paramsPatch);
            node = pipelineNormalizeNodes(node, 'persist');
            node.layout = [targetCol targetRow 1 1];
            app.Data.nodes = appendStruct(app, app.Data.nodes, node);
            app.SelectedNodeIndex = numel(app.Data.nodes);
            app.Data.nodes = sortNodesByLayout(app, app.Data.nodes);
            app.SelectedNodeIndex = find(strcmp({app.Data.nodes.id}, node.id), 1);
            rebuildEdgesFromLayout(app);
            refreshAfterModelChange(app);
        end

        function insertModuleBeforeSelected(app, nodeType, pkg, paramsPatch)
            if nargin < 4
                paramsPatch = struct();
            end
            if isnan(app.SelectedNodeIndex) || app.SelectedNodeIndex < 1 || app.SelectedNodeIndex > numel(app.Data.nodes)
                uialert(app.UIFigure, 'Select a module before inserting.', 'Insert module', 'Icon', 'info');
                return;
            end
            if nargin < 2
                choice = chooseModuleFromLibrary(app, 'Insert module before selected');
                if isempty(choice)
                    return;
                end
                nodeType = choice.type;
                pkg = choice.pkg;
                paramsPatch = getField(app, choice, 'paramsPatch', struct());
            end
            if ~moduleCatalogueAllowsMutation(app, nodeType, pkg, paramsPatch)
                return;
            end
            targetCol = getLayoutCol(app, app.Data.nodes(app.SelectedNodeIndex));
            targetRow = getLayoutRow(app, app.Data.nodes(app.SelectedNodeIndex));
            shiftLayoutRowFromColumn(app, targetRow, targetCol);
            app.NodeCounter = app.NodeCounter + 1;
            node = makePipelineNode(app, nodeType, pkg, app.NodeCounter);
            node.params = mergeStructOverride(app, node.params, paramsPatch);
            node = applyCustomPackagePatchToNode(app, node, paramsPatch);
            node = pipelineNormalizeNodes(node, 'persist');
            node.layout = [targetCol targetRow 1 1];
            app.Data.nodes = appendStruct(app, app.Data.nodes, node);
            app.SelectedNodeIndex = numel(app.Data.nodes);
            app.Data.nodes = sortNodesByLayout(app, app.Data.nodes);
            app.SelectedNodeIndex = find(strcmp({app.Data.nodes.id}, node.id), 1);
            rebuildEdgesFromLayout(app);
            refreshAfterModelChange(app);
        end

        function nodes = sortNodesByLayout(app, nodes)
            if numel(nodes) < 2
                return;
            end
            cols = arrayfun(@(n)getLayoutCol(app, n), nodes);
            rows = arrayfun(@(n)getLayoutRow(app, n), nodes);
            [~, order] = sortrows([cols(:) rows(:)], [1 2]);
            nodes = nodes(order);
        end

        function shiftLayoutRowFromColumn(app, row, firstCol)
            for i = 1:numel(app.Data.nodes)
                if getLayoutRow(app, app.Data.nodes(i)) == row && getLayoutCol(app, app.Data.nodes(i)) >= firstCol
                    app.Data.nodes(i).layout(1) = getLayoutCol(app, app.Data.nodes(i)) + 1;
                end
            end
        end

        function compactLayoutRows(app)
            if isempty(app.Data.nodes)
                return;
            end
            rows = unique(arrayfun(@(n)getLayoutRow(app, n), app.Data.nodes));
            for r = 1:numel(rows)
                row = rows(r);
                idx = find(arrayfun(@(n)getLayoutRow(app, n) == row, app.Data.nodes));
                if numel(idx) < 2
                    continue;
                end
                cols = arrayfun(@(k)getLayoutCol(app, app.Data.nodes(k)), idx);
                [cols, order] = sort(cols);
                idx = idx(order);
                startCol = min(cols);
                for k = 1:numel(idx)
                    app.Data.nodes(idx(k)).layout(1) = startCol + k - 1;
                    app.Data.nodes(idx(k)).layout(2) = row;
                end
            end
        end

        function [nodeType, pkg] = selectedModuleTypeAndPackage(app)
            typeLabel = char(string(app.TypeDropDown.Value));
            subtype = char(string(app.SubtypeDropDown.Value));
            pkg = '';
            switch lower(typeLabel)
                case 'dataloader'
                    nodeType = 'dataLoader';
                    if ~isempty(subtype) && ~strcmpi(subtype, 'dataLoader')
                        pkg = subtype;
                    end
                case 'roi definition'
                    nodeType = subtype;
                case 'processor'
                    nodeType = 'processor';
                    pkg = subtype;
                case 'classifier'
                    nodeType = 'classifier';
                    pkg = subtype;
                case 'custom'
                    nodeType = 'custom';
                    pkg = 'customModule';
                otherwise
                    nodeType = typeLabel;
            end
        end

        function [nodeType, pkg] = selectedLibraryModuleTypeAndPackage(app)
            modules = app.AvailableModules;
            row = [];
            try
                sel = app.UIWorkspacePipelineTable.Selection;
                if ~isempty(sel)
                    row = sel(1,1);
                end
            catch
                row = [];
            end
            if isempty(row) || row < 1 || row > size(modules, 1)
                row = 1;
            end
            if ~moduleCatalogueRowCanAdd(app, row)
                showModuleCatalogueDetails(app, row);
                nodeType = '';
                pkg = '';
                return;
            end
            nodeType = char(string(modules{row,2}));
            pkg = char(string(modules{row,3}));
            if strcmpi(nodeType, 'customPackage')
                choice = resolveCustomPackageChoice(app, 'Select custom package');
                if isempty(choice)
                    nodeType = '';
                    pkg = '';
                else
                    nodeType = choice.type;
                    pkg = choice.pkg;
                end
            end
        end

        function rebuildModuleContextMenus(app)
            if isempty(app.ModuleContextMenu) || ~isvalid(app.ModuleContextMenu) || ...
                    isempty(app.GraphContextMenu) || ~isvalid(app.GraphContextMenu)
                return;
            end
            delete(app.ModuleContextMenu.Children);
            delete(app.GraphContextMenu.Children);
            addModuleLibraryMenu(app, app.ModuleContextMenu, 'Insert module after...', 'insert_after');
            addModuleLibraryMenu(app, app.ModuleContextMenu, 'Insert module before...', 'insert_before');
            addModuleLibraryMenu(app, app.ModuleContextMenu, 'Change module type...', 'change_type');
            uimenu(app.ModuleContextMenu, 'Text', 'Fork graph', ...
                'MenuSelectedFcn', createCallbackFcn(app, @ModuleContextForkSelected, true));
            uimenu(app.ModuleContextMenu, 'Text', 'Merge graph', ...
                'MenuSelectedFcn', createCallbackFcn(app, @ModuleContextMergeSelected, true));
            uimenu(app.ModuleContextMenu, 'Text', 'Delete module', ...
                'MenuSelectedFcn', createCallbackFcn(app, @ModuleContextDeleteSelected, true));
            addModuleLibraryMenu(app, app.GraphContextMenu, 'Add module...', 'add');
        end

        function addModuleLibraryMenu(app, parentMenu, titleText, action)
            root = uimenu(parentMenu, 'Text', titleText);
            groups = moduleMenuGroups(app);
            for g = 1:numel(groups)
                typeMenu = uimenu(root, 'Text', groups(g).label);
                for j = 1:numel(groups(g).rows)
                    idx = groups(g).rows(j);
                    nodeType = char(string(app.AvailableModules{idx,2}));
                    pkg = char(string(app.AvailableModules{idx,3}));
                    label = moduleSubtypeLabel(app, idx);
                    item = uimenu(typeMenu, 'Text', label, ...
                        'MenuSelectedFcn', createCallbackFcn(app, @ModuleLibraryMenuSelected, true));
                    item.UserData = struct('action', char(string(action)), ...
                        'nodeType', nodeType, 'pkg', pkg, 'catalogueRow', idx);
                    if ~moduleCatalogueRowCanAdd(app, idx, false)
                        item.Enable = 'off';
                    end
                end
            end
        end

        function ModuleLibraryMenuSelected(app, event)
            src = getCallbackSource(app, event);
            if isempty(src) || ~isvalid(src) || ~isstruct(src.UserData)
                return;
            end
            data = src.UserData;
            catalogueRow = getField(app, data, 'catalogueRow', []);
            if ~isempty(catalogueRow) && ~moduleCatalogueRowCanAdd(app, catalogueRow)
                showModuleCatalogueDetails(app, catalogueRow);
                return;
            end
            action = char(string(getField(app, data, 'action', '')));
            nodeType = char(string(getField(app, data, 'nodeType', '')));
            pkg = char(string(getField(app, data, 'pkg', '')));
            paramsPatch = struct();
            if strcmpi(nodeType, 'customPackage')
                choice = resolveCustomPackageChoice(app, 'Select custom package');
                if isempty(choice)
                    return;
                end
                nodeType = choice.type;
                pkg = choice.pkg;
                paramsPatch = getField(app, choice, 'paramsPatch', struct());
            end
            switch action
                case 'insert_after'
                    addModuleAfterSelected(app, nodeType, pkg, paramsPatch);
                case 'insert_before'
                    insertModuleBeforeSelected(app, nodeType, pkg, paramsPatch);
                case 'change_type'
                    changeSelectedModuleType(app, nodeType, pkg, paramsPatch);
                case 'add'
                    addModuleOfType(app, nodeType, pkg, paramsPatch);
            end
        end

        function ModuleContextForkSelected(app, event) %#ok<INUSD>
            ForkgraphButtonPushed(app, []);
        end

        function ModuleContextMergeSelected(app, event) %#ok<INUSD>
            MergegraphButtonPushed(app, []);
        end

        function ModuleContextDeleteSelected(app, event) %#ok<INUSD>
            deleteSelectedModule(app);
        end

        function openPluginBrowser(app)
            openBuiltinModuleBrowser(app);
        end

        function openBuiltinModuleBrowser(app)
            try
                detecdiv_modules_browser('Root', repoRoot(app), ...
                    'PackagePaths', app.ModuleCataloguePackagePaths);
            catch ME
                uialert(app.UIFigure, ME.message, 'Module catalogue', 'Icon', 'error');
            end
        end

        function rebuildModulesMenu(app)
            if isempty(app.ModulesMenu) || ~isvalid(app.ModulesMenu)
                return;
            end
            try
                delete(app.ModulesMenu.Children);
            catch
            end
            addModuleLibraryMenu(app, app.ModulesMenu, 'Add module...', 'add');
            uimenu(app.ModulesMenu, 'Text', 'Add custom package...', 'Separator', 'on', ...
                'MenuSelectedFcn', @(~,~)addCustomPackageFromModulesMenu(app));
            uimenu(app.ModulesMenu, 'Text', 'Module catalogue...', 'Separator', 'on', ...
                'MenuSelectedFcn', @(~,~)openBuiltinModuleBrowser(app));
            uimenu(app.ModulesMenu, 'Text', 'Refresh module catalogue', ...
                'MenuSelectedFcn', @(~,~)refreshModuleCatalogue(app));
        end

        function refreshModuleCatalogue(app)
            app.AvailableModules = defaultModuleLibrary(app, true);
            refreshAvailableModuleTable(app);
            rebuildModulesMenu(app);
            rebuildModuleContextMenus(app);
            setRuntimeStatus(app, sprintf('Module catalogue refreshed: %d candidate(s), %d diagnostic(s).', ...
                numel(app.ModuleCatalogueItems), numel(app.ModuleCatalogueDiagnostics)));
        end

        function addCustomPackageFromModulesMenu(app)
            choice = resolveCustomPackageChoice(app, 'Select custom package');
            if isempty(choice)
                return;
            end
            paramsPatch = getField(app, choice, 'paramsPatch', struct());
            addModuleOfType(app, choice.type, choice.pkg, paramsPatch);
            rebuildModulesMenu(app);
        end

        function groups = moduleMenuGroups(app)
            groups = struct('key', {}, 'label', {}, 'rows', {});
            for i = 1:size(app.AvailableModules, 1)
                nodeType = char(string(app.AvailableModules{i,2}));
                [key, label] = moduleTypeMenuGroup(app, nodeType);
                idx = find(strcmp({groups.key}, key), 1);
                if isempty(idx)
                    groups(end+1) = struct('key', key, 'label', label, 'rows', i); %#ok<AGROW>
                else
                    groups(idx).rows(end+1) = i;
                end
            end
        end

        function [key, label] = moduleTypeMenuGroup(app, nodeType) %#ok<INUSD>
            switch lower(char(string(nodeType)))
                case 'dataloader'
                    key = 'dataLoader';
                    label = 'Data loader';
                case {'roipattern','roimanual','roigrid','roitracked'}
                    key = 'roi';
                    label = 'ROI definition';
                case 'roiextract'
                    key = 'roiExtract';
                    label = 'ROI extract';
                case 'processor'
                    key = 'processor';
                    label = 'Processor';
                case 'classifier'
                    key = 'classifier';
                    label = 'Classifier';
                case {'custom','custompackage'}
                    key = 'custom';
                    label = 'Custom';
                otherwise
                    key = char(string(nodeType));
                    label = char(string(nodeType));
            end
        end

        function label = moduleSubtypeLabel(app, idx)
            label = char(string(app.AvailableModules{idx,1}));
        end

        function choice = chooseModuleFromLibrary(app, titleText)
            choice = [];
            labels = cell(size(app.AvailableModules, 1), 1);
            for i = 1:size(app.AvailableModules, 1)
                labels{i} = moduleLibraryLabel(app, i);
            end
            [idx, ok] = listdlg('PromptString', titleText, ...
                'SelectionMode', 'single', ...
                'ListString', labels, ...
                'ListSize', [260 220], ...
                'Name', titleText);
            if ~ok || isempty(idx)
                return;
            end
            if ~moduleCatalogueRowCanAdd(app, idx)
                showModuleCatalogueDetails(app, idx);
                uialert(app.UIFigure, 'This catalogue entry cannot be added. See its status and diagnostics in the module catalogue.', ...
                    'Module unavailable', 'Icon', 'warning');
                return;
            end
            if strcmpi(char(string(app.AvailableModules{idx,2})), 'customPackage')
                choice = resolveCustomPackageChoice(app, titleText);
                return;
            end
            choice = struct( ...
                'type', char(string(app.AvailableModules{idx,2})), ...
                'pkg', char(string(app.AvailableModules{idx,3})));
        end

        function label = moduleLibraryLabel(app, idx)
            name = moduleSubtypeLabel(app, idx);
            nodeType = char(string(app.AvailableModules{idx,2}));
            pkg = char(string(app.AvailableModules{idx,3}));
            if any(strcmpi(nodeType, {'custom','customPackage'}))
                label = name;
            elseif isempty(pkg)
                label = [name ' (' nodeType ')'];
            else
                label = [name ' (' nodeType ' / ' pkg ')'];
            end
        end

        function choice = resolveCustomPackageChoice(app, titleText)
            choice = [];
            startDir = pwd;
            try
                if ~isempty(app.CurrentProject) && isprop(app.CurrentProject, 'io') && ...
                        isstruct(app.CurrentProject.io) && isfield(app.CurrentProject.io, 'path') && ...
                        ~isempty(app.CurrentProject.io.path) && isfolder(app.CurrentProject.io.path)
                    startDir = app.CurrentProject.io.path;
                end
            catch
            end

            selectedDir = uigetdir(startDir, 'Select custom package folder (+pkg)');
            if isequal(selectedDir, 0)
                return;
            end

            info = inspectCustomPackageFolder(app, selectedDir);
            if isempty(info)
                uialert(app.UIFigure, ...
                    ['Select a MATLAB package folder named +pkg that contains process.m or classify.m, ' ...
                     'or select a folder containing one or more +pkg folders.'], ...
                    titleText, 'Icon', 'warning');
                return;
            end

            if numel(info) > 1
                labels = arrayfun(@(x) sprintf('%s (%s)', x.pkg, x.type), info, 'UniformOutput', false);
                [idx, ok] = listdlg('PromptString', 'Choose package', ...
                    'SelectionMode', 'single', ...
                    'ListString', labels, ...
                    'ListSize', [260 180], ...
                    'Name', titleText);
                if ~ok || isempty(idx)
                    return;
                end
                info = info(idx);
            end

            if numel(info.types) > 1
                [idx, ok] = listdlg('PromptString', ['Package ' info.pkg ' exposes multiple entry points.'], ...
                    'SelectionMode', 'single', ...
                    'ListString', info.types, ...
                    'ListSize', [240 120], ...
                    'Name', titleText);
                if ~ok || isempty(idx)
                    return;
                end
                info.type = info.types{idx};
            else
                info.type = info.types{1};
            end

            if ~contains(path, info.root)
                addpath(info.root);
            end
            rehash;
            if exist('detecdiv_plugins_register_root', 'file') == 2
                try
                    detecdiv_plugins_register_root(info.packageDir);
                catch
                end
            end

            app.ModuleCataloguePackagePaths = unique( ...
                [app.ModuleCataloguePackagePaths {info.packageDir}], 'stable');
            app.AvailableModules = defaultModuleLibrary(app);
            refreshAvailableModuleTable(app);
            rebuildModulesMenu(app);
            rebuildModuleContextMenus(app);

            paramsPatch = struct( ...
                'customPackageRoot', info.root, ...
                'customPackageDir', info.packageDir, ...
                'customPackageLoadedAt', char(datetime('now')));
            choice = struct('type', info.type, 'pkg', info.pkg, 'paramsPatch', paramsPatch);
        end

        function info = inspectCustomPackageFolder(app, selectedDir) %#ok<INUSD>
            info = struct('pkg', {}, 'root', {}, 'packageDir', {}, 'types', {}, 'type', {});
            selectedDir = char(string(selectedDir));
            if ~isfolder(selectedDir)
                return;
            end

            [parentDir, folderName] = fileparts(selectedDir);
            if startsWith(folderName, '+')
                candidate = customPackageInfo(app, parentDir, selectedDir);
                if ~isempty(candidate)
                    info = candidate;
                end
                return;
            end

            dirs = dir(fullfile(selectedDir, '+*'));
            dirs = dirs([dirs.isdir]);
            for i = 1:numel(dirs)
                candidateDir = fullfile(selectedDir, dirs(i).name);
                candidate = customPackageInfo(app, selectedDir, candidateDir);
                if ~isempty(candidate)
                    info(end+1) = candidate; %#ok<AGROW>
                end
            end
        end

        function info = customPackageInfo(app, rootDir, packageDir) %#ok<INUSD>
            info = [];
            [~, folderName] = fileparts(packageDir);
            if ~startsWith(folderName, '+')
                return;
            end
            pkg = extractAfter(folderName, 1);
            types = {};
            if isfile(fullfile(packageDir, 'process.m'))
                types{end+1} = 'processor'; %#ok<AGROW>
            end
            if isfile(fullfile(packageDir, 'classify.m'))
                types{end+1} = 'classifier'; %#ok<AGROW>
            end
            if isempty(types)
                return;
            end
            info = struct( ...
                'pkg', char(string(pkg)), ...
                'root', char(string(rootDir)), ...
                'packageDir', char(string(packageDir)), ...
                'types', {types}, ...
                'type', types{1});
        end

        function modules = appendCustomModuleIfMissing(app, modules, info) %#ok<INUSD>
            if isempty(modules)
                modules = {};
            end
            exists = false;
            try
                for i = 1:size(modules, 1)
                    nodeType = char(string(modules{i,2}));
                    pkg = char(string(modules{i,3}));
                    if strcmpi(nodeType, info.type) && strcmp(pkg, info.pkg)
                        exists = true;
                        break;
                    end
                end
            catch
            end
            if ~exists
                modules(end+1,:) = {info.pkg, info.type, info.pkg, ...
                    ['Custom ' info.type ' package: ' info.packageDir]};
            end
        end

        function node = makePipelineNode(app, nodeType, pkg, idx)
            baseId = lower(regexprep([nodeType '_' char(string(pkg))], '[^a-zA-Z0-9]+', '_'));
            baseId = regexprep(baseId, '_+$', '');
            if isempty(baseId)
                baseId = 'module';
            end
            nodeId = sprintf('%s_%d', baseId, idx);

            node = struct();
            node.id = nodeId;
            node.name = nodeId;
            node.type = nodeType;
            node.pkg = pkg;
            node.func = defaultNodeFunction(app, nodeType, pkg);
            node.gui = defaultNodeGui(app, nodeType, pkg);
            node.guiMode = 'replace';
            node.params = applyRuntimeDefaultsToParams(app, nodeType, defaultNodeParams(app, nodeType, pkg));
            node.enabled = true;
            node.status = '';
            node.layout = [1 1 1 1];
            node.uiAdvanced = false;
        end

        function f = defaultNodeFunction(app, nodeType, pkg) %#ok<INUSD>
            pkg = canonicalModulePackageName(app, nodeType, pkg);
            switch lower(char(string(nodeType)))
                case 'dataloader'
                    if ~isempty(pkg) && ~strcmpi(char(string(pkg)), 'dataLoader') && ~isempty(which([char(string(pkg)) '.process']))
                        f = [char(string(pkg)) '.process'];
                    else
                        f = 'dataLoader.process';
                    end
                case {'roipattern','roiidentify'}
                    f = 'roiPattern.process';
                case 'roimanual'
                    f = 'roiManual.process';
                case 'roigrid'
                    f = 'roiGrid.process';
                case 'roitracked'
                    f = 'roiTracked.process';
                case 'roiextract'
                    f = 'roiExtract.process';
                case 'processor'
                    f = [char(string(pkg)) '.process'];
                case 'classifier'
                    f = [char(string(pkg)) '.classify'];
                case 'custom'
                    f = 'customModule.process';
                otherwise
                    f = '';
            end
        end

        function g = defaultNodeGui(app, nodeType, pkg) %#ok<INUSD>
            pkg = canonicalModulePackageName(app, nodeType, pkg);
            switch lower(char(string(nodeType)))
                case 'dataloader'
                    if ~isempty(pkg) && ~strcmpi(char(string(pkg)), 'dataLoader') && ~isempty(which([char(string(pkg)) '.ui']))
                        g = [char(string(pkg)) '.ui'];
                    elseif isempty(pkg) || strcmpi(char(string(pkg)), 'dataLoader')
                        g = 'dataLoader.ui';
                    else
                        g = '';
                    end
                case {'roipattern','roiidentify'}
                    g = 'roiPattern.ui';
                case 'roimanual'
                    g = 'roiManual.ui';
                case 'roigrid'
                    g = 'roiGrid.ui';
                case 'roitracked'
                    g = 'roiTracked.ui';
                case 'roiextract'
                    g = 'roiExtract.ui';
                otherwise
                    g = '';
            end
        end

        function p = defaultNodeParams(app, nodeType, pkg) %#ok<INUSD>
            pkg = canonicalModulePackageName(app, nodeType, pkg);
            p = struct();
            candidates = {};
            ctxArg = struct();
            switch lower(char(string(nodeType)))
                case 'dataloader'
                    if ~isempty(pkg) && ~strcmpi(char(string(pkg)), 'dataLoader')
                        candidates = {[char(string(pkg)) '.setparam'], 'dataLoader.setparam'};
                    else
                        candidates = {'dataLoader.setparam'};
                    end
                case {'roipattern','roiidentify'}
                    candidates = {'roiPattern.setparam'};
                case 'roimanual'
                    candidates = {'roiManual.setparam'};
                case 'roigrid'
                    candidates = {'roiGrid.setparam'};
                case 'roitracked'
                    candidates = {'roiTracked.setparam'};
                case 'roiextract'
                    candidates = {'roiExtract.setparam'};
                case 'processor'
                    if ~isempty(pkg)
                        candidates = {[char(string(pkg)) '.setparam']};
                        ctxArg = moduleSetparamPreviewContext(app, pkg);
                    end
                case 'classifier'
                    candidates = {};
                case 'custom'
                    candidates = {'customModule.setparam'};
            end

            for i = 1:numel(candidates)
                try
                    p = feval(candidates{i}, ctxArg);
                    break;
                catch
                end
            end

            if strcmpi(nodeType, 'processor') || strcmpi(nodeType, 'classifier')
                if ~isstruct(p)
                    p = struct();
                end
                if ~isfield(p, 'pkg') || isempty(p.pkg)
                    p.pkg = pkg;
                end
                if strcmpi(nodeType, 'classifier')
                    switch lower(char(string(pkg)))
                        case 'cnn_lstm'
                            p = applyCnnLstmExecutionDefaults(app, p, struct(), 'missing');
                        case 'cellposesam'
                            p = applyCellposeExecutionDefaults(app, p, struct(), 'missing');
                        case 'sam31'
                            p = applySam31ExecutionDefaults(app, p, struct(), 'missing');
                        case 'trackastra'
                            p = applyTrackastraExecutionDefaults(app, p, struct(), 'missing');
                        case 'deeplab_pixel_classification'
                            p = applyDeeplabPixelExecutionDefaults(app, p, struct(), 'missing');
                        otherwise
                            [p, hasExecutionSpec] = applyClassifierExecutionDefaults(app, p, pkg, [], 'missing');
                            if ~hasExecutionSpec && (~isfield(p, 'outputName') || isempty(p.outputName))
                                p.outputName = char(string(pkg));
                            end
                    end
                elseif strcmpi(nodeType, 'processor') && strcmpi(char(string(pkg)), 'computeMetrics')
                    if ~isfield(p, 'maskChannelCount') || isempty(p.maskChannelCount), p.maskChannelCount = 2; end
                    if ~isfield(p, 'scoreChannelCount') || isempty(p.scoreChannelCount), p.scoreChannelCount = 4; end
                end
            end
            p = applyProjectDefaultOutputParams(app, p);
        end

        function node = applyCustomPackagePatchToNode(app, node, paramsPatch) %#ok<INUSD>
            if ~isstruct(node)
                return;
            end
            if ~isstruct(paramsPatch)
                paramsPatch = struct();
            end
            keys = {'customPackageRoot','customPackageDir','customPackageLoadedAt'};
            for i = 1:numel(keys)
                key = keys{i};
                if isfield(paramsPatch, key)
                    node.(key) = paramsPatch.(key);
                    if ~isfield(node, 'params') || ~isstruct(node.params)
                        node.params = struct();
                    end
                    node.params.(key) = paramsPatch.(key);
                end
            end
        end

        function params = applyRuntimeDefaultsToParams(app, nodeType, params)
            if ~strcmpi(char(string(nodeType)), 'dataLoader')
                return;
            end
            rawDataPath = effectiveRuntimeRawDataPath(app);
            if isempty(strtrim(rawDataPath))
                return;
            end
            if ~isstruct(params)
                params = struct();
            end
            params.path = rawDataPath;
        end

        function names = portNames(app, contract, direction) %#ok<INUSD>
            names = {};
            if ~isstruct(contract)
                return;
            end
            fieldName = 'in';
            if strcmp(direction, 'out')
                fieldName = 'out';
            end
            if isfield(contract, fieldName) && ~isempty(contract.(fieldName))
                names = {contract.(fieldName).name};
            end
        end

        function redrawGraph(app)
            if app.IsRedrawingGraph
                return;
            end
            app.IsRedrawingGraph = true;
            d = openRuntimeProgress(app, 'Pipeline graph', 'Redrawing pipeline graph...');
            cleanupObj = onCleanup(@()setRedrawingGraph(app, false)); %#ok<NASGU>
            progressCleanupObj = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>

            updateRuntimeProgress(app, d, 'Clearing pipeline graph...');
            cla(app.UIGraphAxes);
            hold(app.UIGraphAxes, 'on');
            app.BlockHandles = gobjects(0);
            app.GhostHandles = gobjects(0);
            app.EdgeHandles = gobjects(0);

            nodes = app.Data.nodes;
            selectedRunIds = selectedRunNodeIds(app);
            blockW = 1.55;
            blockH = 0.75;
            gapX = 0.55;
            gapY = 0.28;

            updateRuntimeProgress(app, d, 'Drawing pipeline dependencies...');
            drawImplicitEdges(app, blockW, blockH, gapX, gapY);

            for i = 1:numel(nodes)
                if i == 1 || i == numel(nodes) || mod(i, 5) == 0
                    updateRuntimeProgress(app, d, sprintf('Drawing module %d/%d...', i, numel(nodes)));
                end
                col = getLayoutCol(app, nodes(i));
                row = getLayoutRow(app, nodes(i));
                x = (col - 1) * (blockW + gapX);
                y = -(row - 1) * (blockH + gapY);
                selected = isequal(i, app.SelectedNodeIndex);
                runSelected = isempty(selectedRunIds) || any(strcmp(selectedRunIds, char(string(getField(app, nodes(i), 'id', '')))));
                [face, edge, textColor, subTextColor] = graphNodeSelectionColors(app, nodes(i), selected, runSelected);
                h = rectangle(app.UIGraphAxes, 'Position', [x y blockW blockH], ...
                    'Curvature', 0.08, 'FaceColor', face, 'EdgeColor', edge, ...
                    'LineWidth', ternary(app, selected, 2.8, 1.5), ...
                    'ButtonDownFcn', createCallbackFcn(app, @GraphNodeButtonDown, true));
                h.UserData = struct('nodeIndex', i);
                h.UIContextMenu = app.ModuleContextMenu;
                t1 = text(app.UIGraphAxes, x + blockW/2, y + blockH*0.60, ...
                    char(string(getField(app, nodes(i), 'id', 'module'))), ...
                    'HorizontalAlignment', 'center', 'Interpreter', 'none', ...
                    'FontWeight', 'bold', 'FontSize', 8, 'Color', textColor, ...
                    'ButtonDownFcn', createCallbackFcn(app, @GraphNodeButtonDown, true));
                t1.UserData = struct('nodeIndex', i);
                t1.UIContextMenu = app.ModuleContextMenu;
                t2 = text(app.UIGraphAxes, x + blockW/2, y + blockH*0.24, ...
                    blockTypeLabel(app, nodes(i)), ...
                    'HorizontalAlignment', 'center', 'Interpreter', 'none', ...
                    'FontSize', 7, 'Color', subTextColor, ...
                    'ButtonDownFcn', createCallbackFcn(app, @GraphNodeButtonDown, true));
                t2.UserData = struct('nodeIndex', i);
                t2.UIContextMenu = app.ModuleContextMenu;
                app.BlockHandles(end+1:end+3) = [h t1 t2]; %#ok<AGROW>
            end

            if isempty(nodes)
                ghostCol = 1;
            else
                ghostCol = max(arrayfun(@(n) getLayoutCol(app, n), nodes)) + 1;
            end
            gx = (ghostCol - 1) * (blockW + gapX);
            gy = 0;
            gh = rectangle(app.UIGraphAxes, 'Position', [gx gy blockW blockH], ...
                'Curvature', 0.08, 'FaceColor', [0.92 0.92 0.92], ...
                'EdgeColor', [0.55 0.55 0.55], 'LineStyle', '--', ...
                'LineWidth', 1.4, ...
                'ButtonDownFcn', createCallbackFcn(app, @GraphAddModuleButtonDown, true));
            gh.UIContextMenu = app.GraphContextMenu;
            gt = text(app.UIGraphAxes, gx + blockW/2, gy + blockH/2, '+ module', ...
                'HorizontalAlignment', 'center', 'Interpreter', 'none', ...
                'Color', [0.35 0.35 0.35], 'FontWeight', 'bold', ...
                'ButtonDownFcn', createCallbackFcn(app, @GraphAddModuleButtonDown, true));
            gt.UIContextMenu = app.GraphContextMenu;
            app.GhostHandles = [gh gt];

            updateRuntimeProgress(app, d, 'Drawing resource bindings...');
            drawResourceBindingEdges(app, blockW, blockH, gapX, gapY);

            maxCol = max(ghostCol, 3);
            maxRow = 1;
            if ~isempty(nodes)
                maxRow = max(arrayfun(@(n) getLayoutRow(app, n), nodes));
            end
            xlim(app.UIGraphAxes, [-0.3 maxCol * (blockW + gapX)]);
            ylim(app.UIGraphAxes, [-(maxRow) * (blockH + gapY) blockH + 0.35]);
            axis(app.UIGraphAxes, 'manual');
            hold(app.UIGraphAxes, 'off');
            updateRuntimeProgress(app, d, 'Pipeline graph ready.');
        end

        function GraphNodeButtonDown(app, event)
            if app.IsRedrawingGraph
                return;
            end
            src = getCallbackSource(app, event);
            if isempty(src) || ~isvalid(src) || ~isstruct(src.UserData) || ~isfield(src.UserData, 'nodeIndex')
                return;
            end
            idx = src.UserData.nodeIndex;
            if isempty(idx) || ~isscalar(idx)
                return;
            end
            idx = round(double(idx));
            if graphShiftModifierActive(app) && ~isnan(app.SelectedNodeIndex) && ...
                    app.SelectedNodeIndex >= 1 && app.SelectedNodeIndex <= numel(app.Data.nodes) && ...
                    app.SelectedNodeIndex ~= idx
                reconnectGraphNodes(app, app.SelectedNodeIndex, idx);
                return;
            end
            selectNode(app, idx);
        end

        function GraphBackgroundButtonDown(app, event) %#ok<INUSD>
            clearSelectedNode(app);
        end

        function tf = graphShiftModifierActive(app)
            tf = false;
            try
                modifiers = app.UIFigure.CurrentModifier;
                if ischar(modifiers) || isstring(modifiers)
                    modifiers = cellstr(string(modifiers));
                end
                if iscell(modifiers) && any(strcmpi(modifiers, 'shift'))
                    tf = true;
                    return;
                end
            catch
            end
            try
                tf = strcmpi(char(string(app.UIFigure.SelectionType)), 'extend');
            catch
                tf = false;
            end
        end

        function GraphAddModuleButtonDown(app, event) %#ok<INUSD>
            addModuleFromCurrentSelection(app);
        end

        function src = getCallbackSource(app, event) %#ok<INUSD>
            src = [];
            try
                if isobject(event) && isprop(event, 'Source')
                    src = event.Source;
                    return;
                end
            catch
            end
            try
                if isstruct(event) && isfield(event, 'Source')
                    src = event.Source;
                end
            catch
                src = [];
            end
        end

        function drawImplicitEdges(app, blockW, blockH, gapX, gapY)
            nodes = app.Data.nodes;
            edges = app.Data.edges;
            if isempty(nodes) || isempty(edges)
                return;
            end
            ids = {nodes.id};
            selectedRunIds = selectedRunNodeIds(app);
            for i = 1:numel(edges)
                srcIdx = find(strcmp(ids, char(string(edges(i).from))), 1);
                dstIdx = find(strcmp(ids, char(string(edges(i).to))), 1);
                if isempty(srcIdx) || isempty(dstIdx)
                    continue;
                end
                src = nodes(srcIdx);
                dst = nodes(dstIdx);
                x1 = (getLayoutCol(app, src) - 1) * (blockW + gapX) + blockW;
                y1 = -(getLayoutRow(app, src) - 1) * (blockH + gapY) + blockH/2;
                x2 = (getLayoutCol(app, dst) - 1) * (blockW + gapX);
                y2 = -(getLayoutRow(app, dst) - 1) * (blockH + gapY) + blockH/2;
                srcSelected = isempty(selectedRunIds) || any(strcmp(selectedRunIds, char(string(src.id))));
                dstSelected = isempty(selectedRunIds) || any(strcmp(selectedRunIds, char(string(dst.id))));
                edgeColor = [0.52 0.56 0.60];
                edgeWidth = 1.4;
                if ~(srcSelected && dstSelected)
                    edgeColor = [0.74 0.74 0.74];
                    edgeWidth = 1.0;
                elseif ~edgeContractsCompatible(app, src, dst)
                    edgeColor = [0.62 0.57 0.50];
                    edgeWidth = 1.2;
                end
                h = drawGraphArrow(app, x1, y1, x2, y2, 0.18, edgeColor, edgeWidth);
                app.EdgeHandles(end+1) = h; %#ok<AGROW>
            end
        end

        function drawResourceBindingEdges(app, blockW, blockH, gapX, gapY)
            nodes = app.Data.nodes;
            edges = resourceBindingEdgesForGraph(app);
            if isempty(nodes) || isempty(edges)
                return;
            end
            ids = {nodes.id};
            selectedRunIds = selectedRunNodeIds(app);
            for i = 1:numel(edges)
                srcIdx = find(strcmp(ids, char(string(edges(i).from))), 1);
                dstIdx = find(strcmp(ids, char(string(edges(i).to))), 1);
                if isempty(srcIdx) || isempty(dstIdx)
                    continue;
                end
                src = nodes(srcIdx);
                dst = nodes(dstIdx);
                x1 = (getLayoutCol(app, src) - 1) * (blockW + gapX) + blockW;
                y1 = -(getLayoutRow(app, src) - 1) * (blockH + gapY) + blockH/2;
                x2 = (getLayoutCol(app, dst) - 1) * (blockW + gapX);
                y2 = -(getLayoutRow(app, dst) - 1) * (blockH + gapY) + blockH/2;
                overlapsExplicitEdge = explicitGraphEdgeExists(app, ...
                    char(string(edges(i).from)), char(string(edges(i).to)));
                if overlapsExplicitEdge
                    yOffset = blockH * 0.23;
                    y1 = y1 + yOffset;
                    y2 = y2 + yOffset;
                end
                srcSelected = isempty(selectedRunIds) || any(strcmp(selectedRunIds, char(string(src.id))));
                dstSelected = isempty(selectedRunIds) || any(strcmp(selectedRunIds, char(string(dst.id))));
                [~, srcAccent] = graphNodeColors(app, src);
                edgeColor = srcAccent;
                edgeWidth = 1.35;
                if ~(srcSelected && dstSelected)
                    edgeColor = [0.70 0.70 0.70];
                    edgeWidth = 1.0;
                end
                lane = resourceBindingLaneForSource(app, edges, i);
                if overlapsExplicitEdge
                    % The first regular binding lane has a -0.26*blockH
                    % offset, which almost cancels yOffset above and makes
                    % the dashed resource edge overlap the solid execution
                    % edge. Reserve the neutral/upper lanes when both
                    % relationships connect the same pair of nodes.
                    lane = min(5, lane + 2);
                end
                [hLine, hHead] = drawResourceBindingEdgeRoute(app, x1, y1, x2, y2, blockW, blockH, lane, edgeColor, edgeWidth);
                app.EdgeHandles(end+1) = hLine; %#ok<AGROW>
                app.EdgeHandles(end+1) = hHead; %#ok<AGROW>
            end
        end

        function [hLine, hHead] = drawResourceBindingEdgeRoute(app, x1, y1, x2, y2, blockW, blockH, lane, edgeColor, edgeWidth)
            laneOffset = graphBindingLaneOffset(app, lane, blockH);
            y1 = y1 + laneOffset;
            y2 = y2 + laneOffset;
            headLen = 0.18;
            sameOrBackwardsColumn = x2 <= x1 + 0.20;
            if sameOrBackwardsColumn
                targetRight = x2 + blockW;
                routeX = max(x1, targetRight) + 0.26 + 0.06 * max(0, lane - 1);
                headStartX = targetRight + headLen;
                xs = [x1 routeX routeX headStartX];
                ys = [y1 y1 y2 y2];
                hLine = plot(app.UIGraphAxes, xs, ys, '--', ...
                    'Color', edgeColor, ...
                    'LineWidth', edgeWidth, ...
                    'HitTest', 'off');
                hHead = drawGraphArrow(app, headStartX, y2, targetRight, y2, headLen, edgeColor, edgeWidth);
                return;
            end

            headLen = min(max((x2 - x1) * 0.10, 0.12), 0.24);
            if abs(y2 - y1) < 1e-6
                xs = [x1 x2 - headLen];
                ys = [y1 y1];
            else
                midX = x1 + max(0.18, (x2 - x1) * 0.55);
                xs = [x1 midX midX x2 - headLen];
                ys = [y1 y1 y2 y2];
            end
            hLine = plot(app.UIGraphAxes, xs, ys, '--', ...
                'Color', edgeColor, ...
                'LineWidth', edgeWidth, ...
                'HitTest', 'off');
            hHead = drawGraphArrow(app, x2 - headLen, y2, x2, y2, headLen, edgeColor, edgeWidth);
        end

        function h = drawGraphArrow(app, x1, y1, x2, y2, headLen, edgeColor, edgeWidth)
            % A plain line avoids Quiver's marker/axes listeners during UI redraws.
            delta = [x2 - x1, y2 - y1];
            distance = hypot(delta(1), delta(2));
            xs = [x1 x2];
            ys = [y1 y2];
            if distance > eps
                direction = delta / distance;
                normal = [-direction(2), direction(1)];
                headLen = min(headLen, distance);
                base = [x2 y2] - headLen * direction;
                left = base + 0.4 * headLen * normal;
                right = base - 0.4 * headLen * normal;
                xs = [xs NaN left(1) x2 right(1)];
                ys = [ys NaN left(2) y2 right(2)];
            end
            h = line(app.UIGraphAxes, xs, ys, 'Color', edgeColor, ...
                'LineWidth', edgeWidth, 'HitTest', 'off', 'PickableParts', 'none');
        end

        function edges = resourceBindingEdgesForGraph(app)
            edges = struct('from',{},'to',{},'fromPort',{},'toPort',{},'condition',{});
            if app.RuntimeInventoryMetadataOnly || isempty(fieldnames(app.LastValidationReport))
                % Explicit symbolic wiring is already known without reading
                % ROI files or running model/path validation.
                for iNode = 1:numel(app.Data.nodes)
                    node = app.Data.nodes(iNode);
                    try
                        contract = pipelineNodeContract(node);
                    catch
                        continue;
                    end
                    inputs = getField(app, getField(app, contract, 'resources', struct()), 'in', struct([]));
                    for iInput = 1:numel(inputs)
                        value = bindingDisplayedValue(app, node, inputs(iInput), true);
                        if ~isSymbolicBindingLabel(app, value), continue; end
                        source = symbolicBindingSourceNode(app, value);
                        if isempty(source) || ~any(strcmp({app.Data.nodes.id}, source)), continue; end
                        edges(end+1) = struct('from', source, 'to', char(string(node.id)), ...
                            'fromPort', '', 'toPort', char(string(getField(app, inputs(iInput), 'port', ''))), ...
                            'condition', 'resourceBinding'); %#ok<AGROW>
                    end
                end
                return;
            end
            try
                report = app.LastValidationReport;
                if ~isfield(report, 'edges') || isempty(report.edges)
                    return;
                end
                reportEdges = report.edges;
                conditions = cell(1, numel(reportEdges));
                for i = 1:numel(reportEdges)
                    conditions{i} = char(string(getField(app, reportEdges(i), 'condition', '')));
                end
                reportEdges = reportEdges(strcmpi(conditions, 'resourceBinding'));
                if isempty(reportEdges)
                    return;
                end
                edges = reportEdges;
            catch
                edges = struct('from',{},'to',{},'fromPort',{},'toPort',{},'condition',{});
            end
        end

        function tf = explicitGraphEdgeExists(app, fromId, toId)
            tf = false;
            try
                explicitEdges = app.Data.edges;
                for i = 1:numel(explicitEdges)
                    if strcmp(char(string(getField(app, explicitEdges(i), 'from', ''))), fromId) && ...
                            strcmp(char(string(getField(app, explicitEdges(i), 'to', ''))), toId)
                        tf = true;
                        return;
                    end
                end
            catch
                tf = false;
            end
        end

        function tf = edgeContractsCompatible(app, src, dst)
            tf = false;
            try
                srcContract = pipelineNodeContract(src);
                dstContract = pipelineNodeContract(dst);
                srcOut = getField(app, srcContract, 'out', struct([]));
                dstIn = getField(app, dstContract, 'in', struct([]));
                for i = 1:numel(srcOut)
                    for j = 1:numel(dstIn)
                        if compatiblePortTypes(app, char(string(srcOut(i).type)), char(string(dstIn(j).type)))
                            tf = true;
                            return;
                        end
                    end
                end
            catch
                tf = false;
            end
        end

        function tf = compatiblePortTypes(app, outType, inType) %#ok<INUSD>
            outType = lower(char(string(outType)));
            inType = lower(char(string(inType)));
            tf = strcmp(outType, inType);
            if tf
                return;
            end
            if strcmp(outType, 'dataseriesset') && strcmp(inType, 'dataseriesset')
                tf = true;
            elseif strcmp(outType, 'channelset') && any(strcmp(inType, {'imageset','channelset'}))
                tf = true;
            elseif strcmp(outType, 'imageset') && strcmp(inType, 'imageset')
                tf = true;
            elseif strcmp(outType, 'roilist') && strcmp(inType, 'roilist')
                tf = true;
            elseif strcmp(outType, 'maskset') && strcmp(inType, 'maskset')
                tf = true;
            end
        end

        function [face, edge] = graphNodeColors(app, node) %#ok<INUSD>
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
            switch nodeType
                case 'dataloader'
                    edge = [0.26 0.45 0.68];
                    face = [0.88 0.94 0.99];
                case {'roipattern','roiidentify','roigrid','roimanual','roitracked'}
                    edge = [0.22 0.55 0.42];
                    face = [0.88 0.96 0.92];
                case 'roiextract'
                    edge = [0.49 0.42 0.72];
                    face = [0.93 0.91 0.98];
                case 'classifier'
                    palette = [ ...
                        0.78 0.46 0.20; ...
                        0.67 0.36 0.62; ...
                        0.74 0.31 0.35; ...
                        0.58 0.42 0.18];
                    edge = palette(stablePaletteIndex(app, pkg, size(palette, 1)), :);
                    face = lightenColor(app, edge, 0.78);
                case 'processor'
                    palette = [ ...
                        0.18 0.45 0.74; ...
                        0.10 0.58 0.62; ...
                        0.52 0.43 0.74; ...
                        0.66 0.44 0.22; ...
                        0.36 0.55 0.24; ...
                        0.70 0.33 0.48; ...
                        0.28 0.50 0.50];
                    edge = palette(stablePaletteIndex(app, pkg, size(palette, 1)), :);
                    face = lightenColor(app, edge, 0.80);
                otherwise
                    edge = [0.34 0.42 0.52];
                    face = [0.91 0.94 0.97];
            end
        end

        function idx = stablePaletteIndex(app, text, n) %#ok<INUSD>
            if nargin < 3 || n < 1
                idx = 1;
                return;
            end
            text = char(string(text));
            if isempty(text)
                idx = 1;
                return;
            end
            vals = double(char(text));
            idx = mod(sum(vals .* (1:numel(vals))), n) + 1;
        end

        function color = lightenColor(app, color, amount) %#ok<INUSD>
            color = min(1, color + (1 - color) * amount);
        end

        function color = darkenColor(app, color, amount) %#ok<INUSD>
            color = max(0, color * amount);
        end

        function lane = resourceBindingLaneForSource(app, edges, idx) %#ok<INUSD>
            lane = 1;
            if idx < 1 || idx > numel(edges)
                return;
            end
            fromId = char(string(getField(app, edges(idx), 'from', '')));
            for k = 1:idx
                if strcmp(char(string(getField(app, edges(k), 'from', ''))), fromId)
                    lane = lane + 1;
                end
            end
            lane = mod(lane - 2, 5) + 1;
        end

        function offset = graphBindingLaneOffset(app, lane, blockH) %#ok<INUSD>
            offsets = [-0.26 -0.13 0 0.13 0.26] * blockH;
            lane = max(1, min(numel(offsets), round(double(lane))));
            offset = offsets(lane);
        end

        function label = blockTypeLabel(app, node) %#ok<INUSD>
            label = char(string(getField(app, node, 'type', '')));
            pkg = char(string(getField(app, node, 'pkg', '')));
            if ~isempty(pkg)
                label = sprintf('%s\n%s', label, pkg);
            end
        end

        function [face, edge, textColor, subTextColor] = graphNodeSelectionColors(app, node, selected, runSelected)
            [face, edge] = graphNodeColors(app, node);
            textColor = [0.14 0.18 0.22];
            subTextColor = [0.25 0.25 0.25];
            if ~runSelected
                face = [0.88 0.88 0.88];
                edge = [0.68 0.68 0.68];
                textColor = [0.48 0.48 0.48];
                subTextColor = [0.58 0.58 0.58];
            end
            if selected
                if runSelected
                    edge = darkenColor(app, edge, 0.55);
                    face = lightenColor(app, face, 0.18);
                    textColor = [0.02 0.14 0.36];
                    subTextColor = [0.07 0.20 0.43];
                else
                    face = [0.80 0.86 0.93];
                    edge = [0.20 0.32 0.46];
                    textColor = [0.16 0.20 0.25];
                    subTextColor = [0.24 0.28 0.33];
                end
            end
        end

        function refreshGraphNodeSelection(app)
            % Selection changes appearance only; retain edges and callback sources.
            nodes = app.Data.nodes;
            if app.IsRedrawingGraph
                return;
            end
            if numel(app.BlockHandles) ~= 3 * numel(nodes) || any(~isgraphics(app.BlockHandles))
                redrawGraph(app);
                return;
            end
            selectedRunIds = selectedRunNodeIds(app);
            for i = 1:numel(nodes)
                selected = isequal(i, app.SelectedNodeIndex);
                runSelected = isempty(selectedRunIds) || any(strcmp(selectedRunIds, char(string(getField(app, nodes(i), 'id', '')))));
                [face, edge, textColor, subTextColor] = graphNodeSelectionColors(app, nodes(i), selected, runSelected);
                handles = app.BlockHandles(3*i-2:3*i);
                set(handles(1), 'FaceColor', face, 'EdgeColor', edge, 'LineWidth', ternary(app, selected, 2.8, 1.5));
                handles(2).Color = textColor;
                handles(3).Color = subTextColor;
            end
        end

        function selectNode(app, idx)
            if idx < 1 || idx > numel(app.Data.nodes)
                return;
            end
            app.SelectedNodeIndex = idx;
            node = app.Data.nodes(idx);
            app.IdEditField.Value = char(string(getField(app, node, 'id', '')));
            app.AdvancedmodeCheckBox.Value = false;
            selectTypeControlsForNode(app, node);
            refreshGraphNodeSelection(app);
            selectExistingModuleTab(app, node);
            updateCommonControlsEnableState(app);
        end

        function clearSelectedNode(app)
            if isnan(app.SelectedNodeIndex)
                return;
            end
            app.SelectedNodeIndex = NaN;
            refreshGraphNodeSelection(app);
            updateCommonControlsEnableState(app);
        end

        function reconnectGraphNodes(app, srcIdx, dstIdx)
            if srcIdx < 1 || srcIdx > numel(app.Data.nodes) || dstIdx < 1 || dstIdx > numel(app.Data.nodes)
                return;
            end
            if srcIdx == dstIdx
                selectNode(app, dstIdx);
                return;
            end

            srcNode = app.Data.nodes(srcIdx);
            dstNode = app.Data.nodes(dstIdx);
            srcId = char(string(getField(app, srcNode, 'id', '')));
            dstId = char(string(getField(app, dstNode, 'id', '')));
            [edge, ok, msg] = preferredGraphEdge(app, srcNode, dstNode);
            if ~ok
                uialert(app.UIFigure, msg, 'Reconnect modules', 'Icon', 'warning');
                return;
            end

            app.Data.edges = replaceTargetInputEdge(app, app.Data.edges, edge);
            app.Data.edges = appendStruct(app, app.Data.edges, edge);
            bindingCount = applyResourceBindingsFromSource(app, srcId, dstId);
            alignTargetNodeAfterSource(app, srcId, dstId);

            targetIdx = find(strcmp({app.Data.nodes.id}, dstId), 1);
            if isempty(targetIdx)
                targetIdx = min(dstIdx, numel(app.Data.nodes));
            end
            app.SelectedNodeIndex = targetIdx;
            refreshAfterModelChange(app);

            detail = sprintf('Reconnected %s -> %s', srcId, dstId);
            if bindingCount > 0
                detail = sprintf('%s (%d symbolic binding(s) updated)', detail, bindingCount);
            end
            setRuntimeStatus(app, detail);
        end

        function [edge, ok, msg] = preferredGraphEdge(app, srcNode, dstNode)
            edge = struct('from','','to','','fromPort','','toPort','','condition','');
            ok = false;
            msg = '';
            srcId = char(string(getField(app, srcNode, 'id', '')));
            dstId = char(string(getField(app, dstNode, 'id', '')));
            try
                srcContract = pipelineNodeContract(srcNode);
                dstContract = pipelineNodeContract(dstNode);
                srcOut = getField(app, srcContract, 'out', struct([]));
                dstIn = getField(app, dstContract, 'in', struct([]));
            catch ME
                msg = ['Cannot inspect module contracts: ' ME.message];
                return;
            end
            [fromPort, toPort] = preferredCompatiblePortPair(app, srcOut, dstIn);
            if isempty(fromPort) || isempty(toPort)
                msg = sprintf('No compatible graph port from %s to %s.', srcId, dstId);
                return;
            end
            edge = struct( ...
                'from', srcId, ...
                'to', dstId, ...
                'fromPort', fromPort, ...
                'toPort', toPort, ...
                'condition', '');
            ok = true;
        end

        function [fromPort, toPort] = preferredCompatiblePortPair(app, srcOut, dstIn)
            fromPort = '';
            toPort = '';
            bestScore = -Inf;
            for i = 1:numel(srcOut)
                outType = char(string(getField(app, srcOut(i), 'type', '')));
                outName = char(string(getField(app, srcOut(i), 'name', '')));
                for j = 1:numel(dstIn)
                    inType = char(string(getField(app, dstIn(j), 'type', '')));
                    inName = char(string(getField(app, dstIn(j), 'name', '')));
                    if ~compatiblePortTypes(app, outType, inType)
                        continue;
                    end
                    score = 1;
                    if strcmpi(outType, inType)
                        score = score + 10;
                    end
                    if strcmpi(outName, inName)
                        score = score + 5;
                    end
                    if ~strcmpi(outType, 'roiList') && ~strcmpi(inType, 'roiList')
                        score = score + 20;
                    end
                    if logical(getField(app, dstIn(j), 'required', false))
                        score = score + 1;
                    end
                    if score > bestScore
                        bestScore = score;
                        fromPort = outName;
                        toPort = inName;
                    end
                end
            end
        end

        function edges = replaceTargetInputEdge(app, edges, edge)
            if isempty(edges)
                edges = struct('from',{},'to',{},'fromPort',{},'toPort',{},'condition',{});
                return;
            end
            dstId = char(string(edge.to));
            toPort = char(string(edge.toPort));
            keep = true(size(edges));
            for i = 1:numel(edges)
                if strcmpi(char(string(getField(app, edges(i), 'condition', ''))), 'resourceBinding')
                    continue;
                end
                sameTargetPort = strcmp(char(string(getField(app, edges(i), 'to', ''))), dstId) && ...
                    strcmp(char(string(getField(app, edges(i), 'toPort', ''))), toPort);
                sameExplicitEdge = sameTargetPort && ...
                    strcmp(char(string(getField(app, edges(i), 'from', ''))), char(string(edge.from))) && ...
                    strcmp(char(string(getField(app, edges(i), 'fromPort', ''))), char(string(edge.fromPort)));
                if sameTargetPort || sameExplicitEdge
                    keep(i) = false;
                end
            end
            edges = edges(keep);
        end

        function count = applyResourceBindingsFromSource(app, srcId, dstId)
            count = 0;
            srcIdx = find(strcmp({app.Data.nodes.id}, srcId), 1);
            dstIdx = find(strcmp({app.Data.nodes.id}, dstId), 1);
            if isempty(srcIdx) || isempty(dstIdx)
                return;
            end
            try
                srcContract = pipelineNodeContract(app.Data.nodes(srcIdx));
                dstContract = pipelineNodeContract(app.Data.nodes(dstIdx));
            catch
                return;
            end
            srcSpecs = getField(app, getField(app, srcContract, 'resources', struct()), 'out', struct([]));
            dstSpecs = getField(app, getField(app, dstContract, 'resources', struct()), 'in', struct([]));
            if isempty(srcSpecs) || isempty(dstSpecs)
                return;
            end
            for i = 1:numel(dstSpecs)
                param = char(string(getField(app, dstSpecs(i), 'param', '')));
                if isempty(param)
                    continue;
                end
                [value, ok] = symbolicBindingFromSourceResource(app, srcId, srcSpecs, dstSpecs(i));
                if ~ok
                    continue;
                end
                setNodeInputBindingValue(app, dstId, param, value);
                count = count + 1;
            end
        end

        function [value, ok] = symbolicBindingFromSourceResource(app, srcId, srcSpecs, dstSpec)
            value = '';
            ok = false;
            wantedType = lower(char(string(getField(app, dstSpec, 'type', ''))));
            wantedRole = lower(char(string(getField(app, dstSpec, 'role', ''))));
            for i = 1:numel(srcSpecs)
                outType = lower(char(string(getField(app, srcSpecs(i), 'type', ''))));
                outRole = lower(char(string(getField(app, srcSpecs(i), 'role', ''))));
                if ~resourceSpecCompatibleForUi(app, wantedType, wantedRole, outType, outRole)
                    continue;
                end
                if isempty(outRole)
                    outRole = wantedRole;
                end
                symbol = char(string(getField(app, srcSpecs(i), 'symbol', '')));
                if isempty(symbol)
                    symbol = char(string(outRole));
                end
                value = ['@resource:' symbol ':' char(string(srcId))];
                ok = true;
                return;
            end
        end

        function alignTargetNodeAfterSource(app, srcId, dstId)
            srcIdx = find(strcmp({app.Data.nodes.id}, srcId), 1);
            dstIdx = find(strcmp({app.Data.nodes.id}, dstId), 1);
            if isempty(srcIdx) || isempty(dstIdx)
                return;
            end
            srcCol = getLayoutCol(app, app.Data.nodes(srcIdx));
            srcRow = getLayoutRow(app, app.Data.nodes(srcIdx));
            dstCol = getLayoutCol(app, app.Data.nodes(dstIdx));
            desiredCol = max(srcCol + 1, dstCol);
            if desiredCol ~= dstCol || getLayoutRow(app, app.Data.nodes(dstIdx)) ~= srcRow
                shiftLayoutRowFromColumn(app, srcRow, desiredCol);
                dstIdx = find(strcmp({app.Data.nodes.id}, dstId), 1);
                if isempty(dstIdx)
                    return;
                end
                app.Data.nodes(dstIdx).layout = [desiredCol srcRow 1 1];
            end
            app.Data.nodes = sortNodesByLayout(app, app.Data.nodes);
        end

        function selectTypeControlsForNode(app, node)
            nodeType = char(string(getField(app, node, 'type', '')));
            pkg = char(string(getField(app, node, 'pkg', '')));
            if strcmpi(nodeType, 'dataLoader')
                app.TypeDropDown.Value = 'dataLoader';
                updateSubtypeChoices(app);
                if isempty(pkg), pkg = nodeType; end
                selectExistingNodeSubtype(app, pkg);
            elseif any(strcmpi(nodeType, {'roiPattern','roiManual','roiGrid','roiTracked'}))
                app.TypeDropDown.Value = 'ROI definition';
                updateSubtypeChoices(app);
                selectExistingNodeSubtype(app, nodeType);
            elseif strcmpi(nodeType, 'processor')
                app.TypeDropDown.Value = 'processor';
                updateSubtypeChoices(app);
                selectExistingNodeSubtype(app, pkg);
            elseif strcmpi(nodeType, 'classifier')
                app.TypeDropDown.Value = 'classifier';
                updateSubtypeChoices(app);
                selectExistingNodeSubtype(app, pkg);
            elseif any(strcmp(app.TypeDropDown.Items, nodeType))
                app.TypeDropDown.Value = nodeType;
                updateSubtypeChoices(app);
            end
        end

        function selectExistingNodeSubtype(app, subtype)
            % Keep an existing node visible even if its adapter is currently
            % missing or ambiguous. This does not enable a new module choice.
            if isempty(subtype), return; end
            if ~any(strcmp(app.SubtypeDropDown.Items, subtype))
                items = app.SubtypeDropDown.Items;
                items{end+1} = subtype;
                app.SubtypeDropDown.Items = items;
            end
            app.SubtypeDropDown.Value = subtype;
        end

        function ForkgraphButtonPushed(app, event) %#ok<INUSD>
            if isnan(app.SelectedNodeIndex) || app.SelectedNodeIndex < 1 || app.SelectedNodeIndex > numel(app.Data.nodes)
                uialert(app.UIFigure, 'Select a module before forking.', 'Fork graph', 'Icon', 'info');
                return;
            end
            src = app.Data.nodes(app.SelectedNodeIndex);
            app.NodeCounter = app.NodeCounter + 1;
            node = src;
            node.id = sprintf('%s_branch_%d', char(string(src.id)), app.NodeCounter);
            node.name = node.id;
            node.layout = [getLayoutCol(app, src), nextFreeRowInColumn(app, getLayoutCol(app, src)), 1, 1];
            node = pipelineNormalizeNodes(node, 'persist');
            app.Data.nodes = appendStruct(app, app.Data.nodes, node);
            app.SelectedNodeIndex = numel(app.Data.nodes);
            rebuildEdgesFromLayout(app);
            refreshAfterModelChange(app);
        end

        function MergegraphButtonPushed(app, event) %#ok<INUSD>
            if isnan(app.SelectedNodeIndex) || app.SelectedNodeIndex < 1 || app.SelectedNodeIndex > numel(app.Data.nodes)
                uialert(app.UIFigure, 'Select a module before merging.', 'Merge graph', 'Icon', 'info');
                return;
            end
            app.Data.nodes(app.SelectedNodeIndex).layout(2) = 1;
            rebuildEdgesFromLayout(app);
            refreshAfterModelChange(app);
        end

        function InsertbeforeselectedButtonPushed(app, event) %#ok<INUSD>
            insertModuleBeforeSelected(app);
        end

        function DeleteselectedButtonPushed(app, event) %#ok<INUSD>
            deleteSelectedModule(app);
        end

        function row = nextFreeRowInColumn(app, col)
            row = 1;
            if isempty(app.Data.nodes)
                return;
            end
            rows = [];
            for i = 1:numel(app.Data.nodes)
                if getLayoutCol(app, app.Data.nodes(i)) == col
                    rows(end+1) = getLayoutRow(app, app.Data.nodes(i)); %#ok<AGROW>
                end
            end
            if ~isempty(rows)
                row = max(rows) + 1;
            end
        end

        function rebuildEdgesFromLayout(app)
            edges = struct('from',{},'to',{},'fromPort',{},'toPort',{},'condition',{});
            compactLayoutRows(app);
            nodes = app.Data.nodes;
            if numel(nodes) < 2
                app.Data.edges = edges;
                return;
            end

            cols = unique(arrayfun(@(n) getLayoutCol(app, n), nodes));
            cols = sort(cols);
            for cIdx = 2:numel(cols)
                curCol = cols(cIdx);
                curIdx = find(arrayfun(@(n) getLayoutCol(app, n) == curCol, nodes));
                for ii = 1:numel(curIdx)
                    dstIdx = curIdx(ii);
                    srcCandidates = upstreamLayoutSourceIndices(app, nodes, dstIdx);
                    for jj = 1:numel(srcCandidates)
                        srcIdx = srcCandidates(jj);
                        e = struct( ...
                            'from', char(string(nodes(srcIdx).id)), ...
                            'to', char(string(nodes(dstIdx).id)), ...
                            'fromPort', firstPort(app, nodes(srcIdx), 'out'), ...
                            'toPort', firstPort(app, nodes(dstIdx), 'in'), ...
                            'condition', '');
                        if strcmpi(nodes(srcIdx).type, 'custom') || strcmpi(nodes(dstIdx).type, 'custom')
                            [compatibleEdge, compatible] = preferredGraphEdge(app, nodes(srcIdx), nodes(dstIdx));
                            if compatible, e = compatibleEdge; end
                        end
                        edges = appendStruct(app, edges, e); %#ok<AGROW>
                    end
                end
            end
            app.Data.edges = edges;
        end

        function srcIdx = upstreamLayoutSourceIndices(app, nodes, dstIdx)
            srcIdx = [];
            dstCol = getLayoutCol(app, nodes(dstIdx));
            dstRow = getLayoutRow(app, nodes(dstIdx));
            cols = arrayfun(@(n) getLayoutCol(app, n), nodes);
            rows = arrayfun(@(n) getLayoutRow(app, n), nodes);

            previous = find(cols < dstCol);
            if isempty(previous)
                return;
            end

            sameRow = previous(rows(previous) == dstRow);
            if ~isempty(sameRow)
                nearestCol = max(cols(sameRow));
                srcIdx = sameRow(cols(sameRow) == nearestCol);
                return;
            end

            nearestCol = max(cols(previous));
            srcIdx = previous(cols(previous) == nearestCol);
        end

        function p = firstPort(app, node, direction)
            p = '';
            try
                c = pipelineNodeContract(node);
                if strcmp(direction, 'out') && isfield(c, 'out') && ~isempty(c.out)
                    p = char(string(c.out(1).name));
                elseif strcmp(direction, 'in') && isfield(c, 'in') && ~isempty(c.in)
                    p = char(string(c.in(1).name));
                end
            catch
            end
        end

        function refreshAfterModelChange(app, markDirty)
            if nargin < 2
                markDirty = true;
            end
            d = openRuntimeProgress(app, 'Pipeline UI', 'Refreshing pipeline UI...');
            cleanupObj = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
            updateRuntimeProgress(app, d, 'Refreshing module list...');
            refreshSelectedModuleTable(app);
            refreshCommonControlsFromSelection(app);
            refreshGlobalRuntimeTable(app);
            updateRuntimeProgress(app, d, 'Rebuilding module tabs...');
            refreshModuleTabs(app);
            updateRuntimeProgress(app, d, 'Checking pipeline and redrawing graph...');
            % Resource-binding edges come from LastValidationReport. Draw
            % only after refreshing that report so a newly loaded symbolic
            % binding is visible immediately.
            refreshValidationReport(app, true);
            updateRuntimeProgress(app, d, 'Updating controls...');
            updateCommonControlsEnableState(app);
            if markDirty
                markPipelineDirty(app, true);
            else
                updatePipelineWindowTitle(app);
            end
        end

        function refreshModuleTabs(app)
            if app.ModuleTabRefreshSuspended || app.ModuleTabRefreshBatchDepth > 0
                app.ModuleTabRefreshPending = true;
                return;
            end
            if app.IsRefreshingTabs
                % The active rebuild reads app.Data directly. Re-entrant UI
                % callbacks must not queue an identical second rebuild.
                return;
            end
            app.ModuleTabRefreshPending = false;
            d = openRuntimeProgress(app, 'Pipeline modules', 'Refreshing module tabs...');
            cleanupObj = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
            app.IsRefreshingTabs = true;
            tabCleanupObj = onCleanup(@()setRefreshingTabs(app, false)); %#ok<NASGU>
            previousFocus = captureTabFocus(app);
            updateRuntimeProgress(app, d, 'Clearing old module tabs...');
            deleteDynamicModuleTabs(app);
            nodes = app.Data.nodes;
            activeIds = selectedRunNodeIds(app);
            for i = 1:numel(nodes)
                node = nodes(i);
                updateRuntimeProgress(app, d, sprintf('Building module tab %d/%d: %s', ...
                    i, numel(nodes), char(string(getField(app, node, 'id', 'module')))));
                tabTitle = truncateTabTitle(app, getField(app, node, 'id', 'module'));
                t = uitab(app.TabGroup, 'Title', tabTitle);
                t.UserData = struct('nodeId', char(string(node.id)), 'dynamic', true);
                configureModuleTabActiveState(app, t, node, activeIds);
                app.DynamicModuleTabs(end+1) = t; %#ok<AGROW>
                buildModuleTab(app, t, node);
            end
            updateRuntimeProgress(app, d, 'Restoring module tab focus...');
            reorderRuntimeTabs(app);
            restored = restoreTabFocus(app, previousFocus);
            if ~restored && ~isnan(app.SelectedNodeIndex) && app.SelectedNodeIndex >= 1 && app.SelectedNodeIndex <= numel(app.DynamicModuleTabs)
                selectDynamicModuleTabIfActive(app, app.SelectedNodeIndex);
            end
        end

        function focus = captureTabFocus(app)
            focus = struct('kind', '', 'nodeId', '');
            try
                selectedTab = app.TabGroup.SelectedTab;
                if isstruct(selectedTab.UserData) && isfield(selectedTab.UserData, 'nodeId')
                    focus.kind = 'module';
                    focus.nodeId = char(string(selectedTab.UserData.nodeId));
                end
            catch
            end
        end

        function restored = restoreTabFocus(app, focus)
            restored = false;
            if ~isstruct(focus)
                return;
            end
            try
                switch char(string(getField(app, focus, 'kind', '')))
                    case 'module'
                        nodeId = char(string(getField(app, focus, 'nodeId', '')));
                        for i = 1:numel(app.DynamicModuleTabs)
                            ud = app.DynamicModuleTabs(i).UserData;
                            if isstruct(ud) && isfield(ud, 'nodeId') && strcmp(char(string(ud.nodeId)), nodeId)
                                if isfield(ud, 'active') && ~logical(ud.active)
                                    continue;
                                end
                                app.TabGroup.SelectedTab = app.DynamicModuleTabs(i);
                                idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
                                if ~isempty(idx)
                                    app.SelectedNodeIndex = idx;
                                end
                                restored = true;
                                return;
                            end
                        end
                end
            catch
                restored = false;
            end
        end

        function tf = selectDynamicModuleTabIfActive(app, idx)
            tf = false;
            if idx < 1 || idx > numel(app.DynamicModuleTabs)
                return;
            end
            try
                ud = app.DynamicModuleTabs(idx).UserData;
                if isstruct(ud) && isfield(ud, 'active') && ~logical(ud.active)
                    return;
                end
                app.TabGroup.SelectedTab = app.DynamicModuleTabs(idx);
                tf = true;
            catch
            end
        end

        function configureModuleTabActiveState(app, tab, node, activeIds)
            nodeId = char(string(getField(app, node, 'id', '')));
            isActive = isempty(activeIds) || any(strcmp(activeIds, nodeId));
            baseTitle = truncateTabTitle(app, nodeId);
            if isActive
                tab.Title = baseTitle;
            else
                tab.Title = ['off: ' baseTitle];
            end
            try
                tab.Enable = ternary(app, isActive, 'on', 'off');
            catch
            end
            try
                ud = tab.UserData;
                if ~isstruct(ud)
                    ud = struct();
                end
                ud.nodeId = nodeId;
                ud.dynamic = true;
                ud.active = isActive;
                tab.UserData = ud;
            catch
            end
        end

        function titleText = truncateTabTitle(app, value) %#ok<INUSD>
            titleText = char(string(value));
            if numel(titleText) > 18
                titleText = [titleText(1:15) '...'];
            end
        end

        function refreshGlobalRuntimeTable(app)
            % Runtime inputs now use the designer controls on RunTab.
        end

        function buildRuntimeControls(app)
            if ~hasStaticRuntimeInputControls(app)
                error('pipeline2:MissingRuntimeControls', ...
                    'The App Designer Run tab is missing required runtime input controls.');
            end
            bindStaticRuntimeInputControls(app);
            updateRuntimeInputStates(app);
        end

        function tf = hasStaticRuntimeInputControls(app)
            tf = false;
            try
                tf = ~isempty(app.TemplateidEditField) && isvalid(app.TemplateidEditField) && ...
                    ~isempty(app.RuntimeSourceDropDown) && isvalid(app.RuntimeSourceDropDown) && ...
                    ~isempty(app.RuntimeProjectTargetEditField) && isvalid(app.RuntimeProjectTargetEditField);
            catch
                tf = false;
            end
        end

        function bindStaticRuntimeInputControls(app)
            app.RuntimeFieldHandles = struct();
            app.RuntimeButtonHandles = struct();
            app.RuntimeValues = struct();
            app.RuntimeParseInfo = struct();

            app.RuntimeSourceDropDown.Items = {'Read from existing project','Parse raw images into project','Use classifier attached ROIs'};
            app.RuntimeSourceDropDown.ItemsData = {'existing_rois','raw_dataloader','classifier_rois'};
            app.RuntimeSourceDropDown.Value = 'existing_rois';
            app.RuntimeSourceDropDown.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'inputSourceMode', src.Value);

            app.RuntimeProjectTargetEditField.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'projectPath', src.Value);
            app.RuntimeProjectSelectDropDown.Items = projectDropdownItems(app);
            app.RuntimeProjectSelectDropDown.Value = app.RuntimeProjectSelectDropDown.Items{1};
            app.RuntimeProjectSelectDropDown.ValueChangedFcn = @(src,~)projectDropdownChanged(app, src.Value);
            app.RuntimeBrowseExistingButton.ButtonPushedFcn = @(~,~)runtimeButtonPushed(app, 'projectPath');

            app.RuntimeRawDataEditField.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'rawDataPath', src.Value);
            app.RuntimeBrowseRawDataButton.ButtonPushedFcn = @(~,~)runtimeButtonPushed(app, 'rawDataPath');

            try, app.RuntimeProjectTargetEditField.Placeholder = 'Project .json (or legacy .mat) used as input and/or output container'; catch, end
            try, app.RuntimeRawDataEditField.Placeholder = 'Raw image/data folder parsed by dataloader'; catch, end
            try, app.RuntimeFovsEditField.Placeholder = 'all / 1,3,5 / 1:4'; catch, end
            try, app.RuntimeFramesEditField.Placeholder = 'all / 1:50 / 1,5,9'; catch, end
            try, app.RuntimeRoisEditField.Placeholder = 'all / selected ROI ids'; catch, end
            try, app.RuntimeProjectTargetEditField.Tooltip = 'Project container. In project-input mode it supplies existing FOV/ROI/dataseries. In raw-input mode it receives loaded FOVs, ROIs and outputs.'; catch, end
            try, app.RuntimeRawDataEditField.Tooltip = 'Raw image/data folder parsed by dataloader. Required only when the run starts from raw images.'; catch, end
            try, app.RuntimeFovsEditField.Tooltip = 'all / 1,3,5 / 1:4'; catch, end
            try, app.RuntimeFramesEditField.Tooltip = 'all / 1:50 / 1,5,9'; catch, end
            try, app.RuntimeRoisEditField.Tooltip = 'all / selected ROI ids'; catch, end

            app.RuntimeFovsEditField.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'fovs', src.Value);
            app.RuntimeFramesEditField.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'frames', src.Value);
            app.RuntimeRoisEditField.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'rois', src.Value);

            app.RuntimeOutputPolicyDropDown.Items = {'Skip existing outputs','Replace existing outputs','Append/update existing outputs','Error if outputs exist'};
            app.RuntimeOutputPolicyDropDown.ItemsData = {'skip','replace','upsert','error'};
            app.RuntimeOutputPolicyDropDown.Value = 'skip';
            app.RuntimeOutputPolicyDropDown.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'outputPolicy', src.Value);

            app.TemplateidEditField.ValueChangedFcn = @(src,~)runtimeRunIdChanged(app, src.Value);

            app.RuntimeFieldHandles.inputSourceMode = app.RuntimeSourceDropDown;
            app.RuntimeFieldHandles.projectPath = app.RuntimeProjectTargetEditField;
            app.RuntimeFieldHandles.projectSource = app.RuntimeProjectSelectDropDown;
            app.RuntimeFieldHandles.rawDataPath = app.RuntimeRawDataEditField;
            app.RuntimeFieldHandles.availableResources = app.RuntimeAvailableTextArea;
            app.RuntimeFieldHandles.fovs = app.RuntimeFovsEditField;
            app.RuntimeFieldHandles.frames = app.RuntimeFramesEditField;
            app.RuntimeFieldHandles.rois = app.RuntimeRoisEditField;
            app.RuntimeFieldHandles.outputPolicy = app.RuntimeOutputPolicyDropDown;
            app.RuntimeFieldHandles.runId = app.TemplateidEditField;

            app.RuntimeButtonHandles.projectPath = app.RuntimeBrowseExistingButton;
            app.RuntimeButtonHandles.rawDataPath = app.RuntimeBrowseRawDataButton;

            app.RuntimeValues.inputSourceMode = 'existing_rois';
            app.RuntimeValues.projectPath = '';
            app.RuntimeValues.rawDataPath = '';
            app.RuntimeValues.fovs = '';
            app.RuntimeValues.frames = '';
            app.RuntimeValues.rois = '';
            app.RuntimeValues.outputPolicy = 'skip';
            app.RuntimeValues.outputPolicyUserChosen = false;
            app.RuntimeValues.runId = '';
            app.RuntimeValues.intent = 'infer';
            refreshProjectDropdown(app);
        end

        function deleteRuntimeInputChildren(app)
            try
                kids = app.RunTab.Children;
                for k = 1:numel(kids)
                    delete(kids(k));
                end
            catch
            end
        end

        function buildHubRuntimeControls(app)
            deleteHubRuntimeControls(app);
            hub = defaultHubSettingsForUi(app);
            app.HubFieldHandles = struct();

            app.RunTargetDropDown.Items = {'Local / Windows','Local / WSL','DetecDiv Hub'};
            app.RunTargetDropDown.ItemsData = {'local','local_wsl','hub'};
            app.RunTargetDropDown.Value = 'local';
            target = app.RunTargetDropDown;
            app.HubFieldHandles.executionTargetLabel = app.RunTargetDropDownLabel;
            target.ValueChangedFcn = createCallbackFcn(app, @HubExecutionTargetChanged, true);
            app.HubFieldHandles.executionTarget = target;
            app.RuntimeValues.executionTarget = 'local';

            grid = uigridlayout(app.HubparametersPanel, [8 3]);
            grid.RowHeight = repmat({28}, 1, 8);
            grid.ColumnWidth = {92, '1x', 76};
            grid.Padding = [8 8 8 8];
            grid.RowSpacing = 5;
            grid.ColumnSpacing = 6;
            app.HubFieldHandles.container = grid;

            labels = {'Hub URL','Fallback URLs','User key','Password','Session token','Timeout','Remote root','Local root'};
            keys = {'baseUrl','fallbackBaseUrls','userKey','password','sessionToken','timeout','defaultRemoteProjectRoot','defaultLocalProjectRoot'};
            defaults = { ...
                getStructText(app, hub, 'baseUrl', 'http://detecdiv-hub.detecdiv.internal'), ...
                strjoin(normalizeHubStringList(app, getStructValue(app, hub, 'fallbackBaseUrls', {'http://127.0.0.1:8000'})), ', '), ...
                getStructText(app, hub, 'userKey', 'localdev'), ...
                '', ...
                getStructText(app, hub, 'sessionToken', ''), ...
                num2str(double(getStructValue(app, hub, 'timeout', 20))), ...
                getStructText(app, hub, 'defaultRemoteProjectRoot', ''), ...
                getStructText(app, hub, 'defaultLocalProjectRoot', '') ...
                };

            for i = 1:numel(keys)
                lbl = uilabel(grid, 'Text', labels{i}, 'HorizontalAlignment', 'right');
                lbl.Layout.Row = i;
                lbl.Layout.Column = 1;
                if strcmp(keys{i}, 'timeout')
                    fld = uieditfield(grid, 'numeric', 'Value', str2double(defaults{i}));
                elseif strcmp(keys{i}, 'password')
                    fld = createHubPasswordControl(app, grid, [1 1 10 10]);
                    app.HubFieldHandles.connectButton = uibutton(grid, 'push', ...
                        'Text', 'Connect', ...
                        'ButtonPushedFcn', @(~,~)connectHubButtonPushed(app));
                    app.HubFieldHandles.connectButton.Layout.Row = i;
                    app.HubFieldHandles.connectButton.Layout.Column = 3;
                else
                    fld = uieditfield(grid, 'text', 'Value', defaults{i});
                end
                fld.Layout.Row = i;
                if strcmp(keys{i}, 'password')
                    fld.Layout.Column = 2;
                else
                    fld.Layout.Column = [2 3];
                end
                if strcmp(keys{i}, 'password') && isprop(fld, 'DataChangedFcn')
                    fld.DataChangedFcn = @(src,~)hubRuntimeFieldChanged(app, 'hubPassword', src.Data);
                else
                    fld.ValueChangedFcn = @(src,~)hubRuntimeFieldChanged(app, '', []);
                end
                app.HubFieldHandles.([keys{i} 'Label']) = lbl;
                app.HubFieldHandles.(keys{i}) = fld;
            end
            updateHubRuntimeControlsVisibility(app);
        end

        function buildRunArtifactControls(app)
            deleteRunArtifactControls(app);
            app.RunArtifactButtonHandles = struct();
            if ~isempty(app.OpenRunFolderButton) && isvalid(app.OpenRunFolderButton)
                app.RunArtifactButtonHandles.folder = app.OpenRunFolderButton;
                app.RunArtifactButtonHandles.log = app.RunLogButton;
                app.RunArtifactButtonHandles.params = app.RunParamsButton;
                app.RunArtifactButtonHandles.review = app.ReviewRunButton;
                app.OpenRunFolderButton.ButtonPushedFcn = @(~,~)openCurrentRunArtifact(app, 'folder');
                app.RunLogButton.ButtonPushedFcn = @(~,~)showCurrentRunLog(app);
                app.RunParamsButton.ButtonPushedFcn = @(~,~)openCurrentRunArtifact(app, 'params');
                app.ReviewRunButton.ButtonPushedFcn = @(~,~)showCurrentRunReview(app);
            else
                app.RunArtifactButtonHandles.folder = uibutton(app.MonitorTab, 'push', ...
                    'Text', 'Open run folder', 'Position', [660 404 130 24], ...
                    'ButtonPushedFcn', @(~,~)openCurrentRunArtifact(app, 'folder'));
                app.RunArtifactButtonHandles.log = uibutton(app.MonitorTab, 'push', ...
                    'Text', 'Run log', 'Position', [660 368 130 24], ...
                    'ButtonPushedFcn', @(~,~)showCurrentRunLog(app));
                app.RunArtifactButtonHandles.params = uibutton(app.MonitorTab, 'push', ...
                    'Text', 'Run params', 'Position', [660 332 130 24], ...
                    'ButtonPushedFcn', @(~,~)openCurrentRunArtifact(app, 'params'));
                app.RunArtifactButtonHandles.review = uibutton(app.MonitorTab, 'push', ...
                    'Text', 'Review run', 'Position', [660 296 130 24], ...
                    'ButtonPushedFcn', @(~,~)showCurrentRunReview(app));
            end
        end

        function deleteRunArtifactControls(app)
            if ~isstruct(app.RunArtifactButtonHandles) || isempty(fieldnames(app.RunArtifactButtonHandles))
                return;
            end
            fn = fieldnames(app.RunArtifactButtonHandles);
            for i = 1:numel(fn)
                h = app.RunArtifactButtonHandles.(fn{i});
                try
                    if isvalid(h) && ~isDesignRuntimeArtifactButton(app, h)
                        delete(h);
                    end
                catch
                end
            end
            app.RunArtifactButtonHandles = struct();
        end

        function tf = isDesignRuntimeArtifactButton(app, h)
            tf = false;
            try
                tf = isequal(h, app.OpenRunFolderButton) || isequal(h, app.RunLogButton) || ...
                    isequal(h, app.RunParamsButton) || isequal(h, app.ReviewRunButton);
            catch
                tf = false;
            end
        end

        function deleteHubRuntimeControls(app)
            if ~isstruct(app.HubFieldHandles) || isempty(fieldnames(app.HubFieldHandles))
                return;
            end
            fn = fieldnames(app.HubFieldHandles);
            for i = 1:numel(fn)
                h = app.HubFieldHandles.(fn{i});
                try
                    if isvalid(h) && ~isDesignHubRuntimeControl(app, h)
                        delete(h);
                    end
                catch
                end
            end
            app.HubFieldHandles = struct();
        end

        function tf = isDesignHubRuntimeControl(app, h)
            tf = false;
            try
                tf = isequal(h, app.RunTargetDropDown) || isequal(h, app.RunTargetDropDownLabel);
            catch
                tf = false;
            end
        end

        function HubExecutionTargetChanged(app, event)
            src = getCallbackSource(app, event);
            if isempty(src) || ~isvalid(src)
                return;
            end
            d = openRuntimeProgress(app, 'Run target', 'Updating execution target...');
            progressCleanup = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
            hubRuntimeFieldChanged(app, 'executionTarget', src.Value);
        end

        function hubRuntimeFieldChanged(app, key, value)
            if nargin >= 3 && ~isempty(key)
                if strcmp(char(string(key)), 'hubPassword') && isstruct(value)
                    return;
                end
                app.RuntimeValues.(key) = char(string(value));
            end
            persistHubSettingsFromUi(app);
            updateHubRuntimeControlsVisibility(app);
            refreshValidationReport(app);
        end

        function updateHubRuntimeControlsVisibility(app)
            if ~isstruct(app.HubFieldHandles) || ~isfield(app.HubFieldHandles, 'executionTarget')
                return;
            end
            isHub = strcmp(char(string(app.HubFieldHandles.executionTarget.Value)), 'hub');
            app.HubparametersPanel.Visible = ternary(app, isHub, 'on', 'off');
        end

        function hub = defaultHubSettingsForUi(app) %#ok<INUSD>
            try
                hub = detecdiv_hub_settings_get();
            catch
                hub = struct('baseUrl', 'http://detecdiv-hub.detecdiv.internal', ...
                    'fallbackBaseUrls', {{'http://127.0.0.1:8000'}}, ...
                    'userKey', 'localdev', 'sessionToken', '', 'timeout', 20, ...
                    'defaultRemoteProjectRoot', '', 'defaultLocalProjectRoot', '');
            end
        end

        function value = getStructValue(app, S, key, defaultValue) %#ok<INUSD>
            value = defaultValue;
            if isstruct(S) && isfield(S, key) && ~isempty(S.(key))
                value = S.(key);
            end
        end

        function value = getStructText(app, S, key, defaultValue)
            value = char(string(getStructValue(app, S, key, defaultValue)));
        end

        function ctrl = createHubPasswordControl(app, parent, position) %#ok<INUSD>
            try
                ctrl = uihtml(parent, ...
                    'HTMLSource', hubPasswordHtml(app), ...
                    'Position', position, ...
                    'Data', '');
            catch
                ctrl = uieditfield(parent, 'text', 'Position', position, 'Value', '');
            end
        end

        function html = hubPasswordHtml(app) %#ok<INUSD>
            html = [ ...
                '<!doctype html><html><head><meta charset="utf-8">' ...
                '<style>' ...
                'html,body{margin:0;padding:0;background:transparent;overflow:hidden;}' ...
                'input{box-sizing:border-box;width:100%;height:22px;border:1px solid #8f8f8f;' ...
                'font:12px Arial,Helvetica,sans-serif;padding:1px 4px;background:white;color:#111;}' ...
                'input:focus{outline:1px solid #0072bd;border-color:#0072bd;}' ...
                '</style></head><body>' ...
                '<input id="hubPassword" type="password" autocomplete="off" spellcheck="false">' ...
                '<script>' ...
                'const input=document.getElementById("hubPassword");' ...
                'function push(){htmlComponent.Data=input.value;}' ...
                'input.addEventListener("input",push);' ...
                'input.addEventListener("change",push);' ...
                'input.addEventListener("blur",push);' ...
                'input.addEventListener("keyup",push);' ...
                'htmlComponent.addEventListener("DataChanged",function(){' ...
                'const d=htmlComponent.Data;' ...
                'if(d&&typeof d==="object"&&d.command==="flush"){push();return;}' ...
                'const v=(typeof d==="string")?d:"";' ...
                'if(input.value!==v){input.value=v;}' ...
                '});' ...
                '</script></body></html>' ...
                ];
        end

        function values = normalizeHubStringList(app, value) %#ok<INUSD>
            if isempty(value)
                values = {};
            elseif iscell(value)
                values = cellstr(string(value(:)'));
            elseif isstring(value)
                values = cellstr(value(:)');
            elseif ischar(value)
                values = cellstr(strsplit(value, ','));
            else
                values = cellstr(string(value(:)'));
            end
            values = cellfun(@(s)strtrim(char(string(s))), values, 'UniformOutput', false);
            values = values(~cellfun(@isempty, values));
        end

        function addRuntimeProjectRow(app, grid, row)
            label = uilabel(grid, 'Text', 'Project');
            label.Tooltip = 'Project container. In project-input mode it supplies existing FOV/ROI/dataseries. In raw-input mode it receives loaded FOVs, ROIs and outputs.';
            label.Layout.Row = row;
            label.Layout.Column = 1;

            field = uieditfield(grid, 'text');
            field.Layout.Row = row;
            field.Layout.Column = 2;
            try
                field.Placeholder = 'Project .json (or legacy .mat) used as input and/or output container';
            catch
            end
            field.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'projectPath', src.Value);

            dd = uidropdown(grid);
            dd.Layout.Row = row;
            dd.Layout.Column = 3;
            dd.Items = projectDropdownItems(app);
            dd.Value = dd.Items{1};
            dd.ValueChangedFcn = @(src,~)projectDropdownChanged(app, src.Value);

            btn = uibutton(grid, 'push', 'Text', 'Browse existing...');
            btn.Layout.Row = row;
            btn.Layout.Column = 4;
            btn.Tooltip = 'Load an existing DetecDiv project JSON or legacy MAT file.';
            btn.ButtonPushedFcn = @(~,~)runtimeButtonPushed(app, 'projectPath');

            app.RuntimeFieldHandles.projectPath = field;
            app.RuntimeFieldHandles.projectSource = dd;
            app.RuntimeButtonHandles.projectPath = btn;
            app.RuntimeValues.projectPath = '';
        end

        function addRuntimeInputSourceRow(app, grid, row)
            label = uilabel(grid, 'Text', 'Input mode');
            label.Layout.Row = row;
            label.Layout.Column = 1;
            label.Tooltip = 'Choose the authority for this run: existing project data, or raw images parsed into a project.';

            dd = uidropdown(grid);
            dd.Layout.Row = row;
            dd.Layout.Column = [2 4];
            dd.ItemsData = {'existing_rois','raw_dataloader','classifier_rois'};
            dd.Items = {'Read from existing project','Parse raw images into project','Use classifier attached ROIs'};
            dd.Value = 'existing_rois';
            dd.Tooltip = ['Read from existing project: use FOV/ROI/channels/dataseries already stored in the selected project; raw-image nodes require saved FOV image sources. ' ...
                'Parse raw images into project: use the raw folder as the image source and write loaded FOVs, ROIs and outputs into the selected project. ' ...
                'Use classifier attached ROIs: classify the ROI objects imported into the classifier, without selecting a project or raw folder.'];
            dd.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'inputSourceMode', src.Value);

            app.RuntimeFieldHandles.inputSourceMode = dd;
            app.RuntimeValues.inputSourceMode = 'existing_rois';
        end

        function addRuntimeRow(app, grid, row, labelText, key, placeholder, buttonText)
            label = uilabel(grid, 'Text', labelText);
            label.Layout.Row = row;
            label.Layout.Column = 1;

            field = uieditfield(grid, 'text');
            field.Layout.Row = row;
            field.Layout.Column = [2 3];
            try
                field.Placeholder = placeholder;
            catch
                field.Value = '';
            end
            field.Tooltip = placeholder;
            field.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, key, src.Value);

            btn = uibutton(grid, 'push', 'Text', buttonText);
            btn.Layout.Row = row;
            btn.Layout.Column = 4;
            btn.Tooltip = placeholder;
            btn.ButtonPushedFcn = @(~,~)runtimeButtonPushed(app, key);

            app.RuntimeFieldHandles.(key) = field;
            app.RuntimeButtonHandles.(key) = btn;
            app.RuntimeValues.(key) = '';
        end

        function addRuntimeTextRow(app, grid, row, labelText, key, placeholder)
            label = uilabel(grid, 'Text', labelText);
            label.Layout.Row = row;
            label.Layout.Column = 1;

            field = uieditfield(grid, 'text');
            field.Layout.Row = row;
            field.Layout.Column = [2 4];
            try
                field.Placeholder = placeholder;
            catch
                field.Value = '';
            end
            field.Tooltip = placeholder;
            field.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, key, src.Value);

            app.RuntimeFieldHandles.(key) = field;
            app.RuntimeValues.(key) = '';
        end

        function addRuntimeChannelRow(app, grid, row)
            label = uilabel(grid, 'Text', 'Channels');
            label.Layout.Row = row;
            label.Layout.Column = 1;

            dd = uidropdown(grid);
            dd.Layout.Row = row;
            dd.Layout.Column = [2 3];
            dd.Items = {'resolved after project/raw data load'};
            dd.Value = dd.Items{1};
            dd.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'channels', src.Value);

            btn = uibutton(grid, 'push', 'Text', 'Select...');
            btn.Layout.Row = row;
            btn.Layout.Column = 4;
            btn.Tooltip = 'Select channel after project/raw data parsing.';
            btn.ButtonPushedFcn = @(~,~)runtimeButtonPushed(app, 'channels');

            app.RuntimeFieldHandles.channels = dd;
            app.RuntimeButtonHandles.channels = btn;
            app.RuntimeValues.channels = '';
        end

        function addRuntimeInventoryRow(app, grid, row)
            label = uilabel(grid, 'Text', 'Available');
            label.Layout.Row = row;
            label.Layout.Column = 1;
            label.Tooltip = 'Informational inventory exposed to module bindings; not a global run selection.';

            txt = uitextarea(grid);
            txt.Layout.Row = row;
            txt.Layout.Column = [2 4];
            txt.Editable = 'off';
            txt.Value = {'Run summary: select an input mode and project/raw folder'; 'Resources: resolved after project/raw data load'};
            txt.Tooltip = 'Execution summary and resources discovered from the selected project/raw data. Use module Bindings to select concrete channels/dataseries.';

            app.RuntimeFieldHandles.availableResources = txt;
        end

        function addRuntimePolicyRow(app, grid, row)
            label = uilabel(grid, 'Text', 'Output policy');
            label.Layout.Row = row;
            label.Layout.Column = 1;

            dd = uidropdown(grid);
            dd.Layout.Row = row;
            dd.Layout.Column = [2 4];
            dd.Items = {'Skip existing outputs','Replace existing outputs','Append/update existing outputs','Error if outputs exist'};
            dd.ItemsData = {'skip','replace','upsert','error'};
            dd.Value = 'skip';
            dd.Tooltip = sprintf(['Controls what happens when module outputs already exist.\n\n' ...
                'Skip existing outputs: keep existing outputs and only compute missing ones when possible. Best with Resume previous progress.\n' ...
                'Replace existing outputs: overwrite outputs produced by the selected modules. Best with Restart from scratch.\n' ...
                'Append/update existing outputs: preserve existing content and add/update entries where the backend supports upsert behavior.\n' ...
                'Error if outputs exist: stop early if a target output already exists.\n\n' ...
                'Resume options are separate: they control run checkpoints/progress, not overwrite behavior.']);
            dd.ValueChangedFcn = @(src,~)runtimeFieldChanged(app, 'outputPolicy', src.Value);

            app.RuntimeFieldHandles.outputPolicy = dd;
            app.RuntimeValues.outputPolicy = 'skip';
            app.RuntimeValues.outputPolicyUserChosen = false;
        end

        function runtimeFieldChanged(app, key, value)
            beginModuleTabRefreshBatch(app);
            tabBatchCleanup = onCleanup(@()endModuleTabRefreshBatch(app, true)); %#ok<NASGU>
            app.RuntimeValues.(key) = char(string(value));
            markRunDirty(app, true);
            if strcmp(char(string(key)), 'inputSourceMode')
                d = openRuntimeProgress(app, 'Runtime source', 'Switching runtime source...');
                cleanupObj = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
                applyRuntimeInputSourceMode(app, value, d);
                return;
            end
            if strcmp(char(string(key)), 'projectPath')
                bindProjectFromPath(app, char(string(value)), false);
            end
            syncRuntimeValueToNodeParams(app, key);
            if strcmp(char(string(key)), 'rawDataPath')
                parseRuntimeRawDataPath(app, char(string(value)));
            end
            if strcmp(char(string(key)), 'outputPolicy')
                markOutputPolicyUserChosen(app);
            end
            updateRuntimeInputStates(app);
            if any(strcmp(char(string(key)), {'projectPath','rawDataPath','fovs','rois'}))
                updateRuntimeResourceInventory(app);
            end
            if runtimeValueAffectsBindings(app, key)
                redrawGraph(app);
                refreshValidationReport(app, false);
                refreshModuleTabs(app);
            else
                refreshValidationReport(app, true);
            end
        end

        function applyRuntimeInputSourceMode(app, value, d)
            if nargin < 3
                d = [];
            end
            beginModuleTabRefreshBatch(app);
            tabBatchCleanup = onCleanup(@()endModuleTabRefreshBatch(app, true)); %#ok<NASGU>
            value = char(string(value));
            app.RuntimeValues.inputSourceMode = value;
            setRuntimeControlValue(app, 'inputSourceMode', value);

            updateRuntimeProgress(app, d, 'Refreshing runtime source...');
            updateRuntimeProgress(app, d, 'Refreshing project list...');
            refreshProjectDropdown(app);

            if strcmpi(value, 'classifier_rois')
                updateRuntimeProgress(app, d, 'Using classifier-attached ROI inventory...');
                updateRuntimeResourceInventory(app);
            elseif strcmpi(value, 'raw_dataloader')
                rawDataPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
                if (isempty(rawDataPath) || strcmpi(rawDataPath, 'Project source path not resolved')) && ...
                        isfield(app.RuntimeValues, 'rawDataPathActive') && ~isempty(app.RuntimeValues.rawDataPathActive)
                    rawDataPath = char(string(app.RuntimeValues.rawDataPathActive));
                    setRuntimeControlValue(app, 'rawDataPath', rawDataPath);
                    app.RuntimeValues.rawDataPath = rawDataPath;
                end
                if ~isempty(rawDataPath) && ~strcmpi(rawDataPath, 'Project source path not resolved')
                    updateRuntimeProgress(app, d, 'Parsing raw data inventory...');
                    parseRuntimeRawDataPath(app, rawDataPath);
                else
                    updateRuntimeProgress(app, d, 'Refreshing available resources...');
                    updateRuntimeResourceInventory(app);
                end
            else
                if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                    updateRuntimeProgress(app, d, 'Loading project inventory...');
                    refreshRuntimeFromProject(app);
                end
                updateRuntimeProgress(app, d, 'Refreshing available resources...');
                updateRuntimeResourceInventory(app);
            end

            updateRuntimeProgress(app, d, 'Updating runtime controls...');
            updateRuntimeInputStates(app);
            updateRuntimeProgress(app, d, 'Redrawing pipeline graph...');
            redrawGraph(app);
            updateRuntimeProgress(app, d, 'Checking pipeline...');
            refreshValidationReport(app, false);
            updateRuntimeProgress(app, d, 'Refreshing module tabs...');
            refreshModuleTabs(app);
        end

        function tf = runtimeValueAffectsBindings(app, key) %#ok<INUSD>
            tf = any(strcmp(char(string(key)), {'inputSourceMode','projectPath','rawDataPath','fovs','frames','rois'}));
        end

        function runtimeButtonPushed(app, key)
            switch char(string(key))
                case 'projectPath'
                    if runtimeStartsFromClassifier(app)
                        refreshProjectDropdown(app);
                    elseif runtimeStartsFromExistingProject(app)
                        chooseExistingProject(app);
                    else
                        createNewProjectFromDialog(app);
                    end
                case 'rawDataPath'
                    if runtimeStartsFromExistingProject(app)
                        relinkProjectRawDataPaths(app);
                        return;
                    end
                    pth = uigetdir(pwd, 'Select raw data folder');
                    if isequal(pth, 0)
                        return;
                    end
                    setRuntimeValue(app, key, pth);
                case {'fovs','frames','rois'}
                    current = getRuntimeValue(app, key);
                    answer = inputdlg(runtimePromptForKey(app, key), ['Set ' key], 1, {current});
                    if isempty(answer)
                        return;
                    end
                    setRuntimeValue(app, key, strtrim(answer{1}));
                case 'outputPolicy'
                    showOutputPolicyHelp(app);
            end
        end

        function relinkProjectRawDataPaths(app)
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                uialert(app.UIFigure, 'Load an existing shallow project before relinking raw data paths.', ...
                    'Relink raw data', 'Icon', 'warning');
                return;
            end
            startDir = pwd;
            try
                rawPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
                if ~isempty(rawPath) && exist(rawPath, 'dir') == 7
                    startDir = rawPath;
                else
                    [pth, ~] = app.CurrentProject.getPath;
                    if ~isempty(pth) && exist(pth, 'dir') == 7
                        startDir = pth;
                    end
                end
            catch
            end
            root = uigetdir(startDir, 'Select raw data root or dataset folder for relink');
            if isequal(root, 0)
                return;
            end

            d = [];
            try
                d = uiprogressdlg(app.UIFigure, 'Title', 'Relink raw data', ...
                    'Message', 'Rebasing project FOV source paths...', 'Indeterminate', 'on');
                ddProgressGuard1 = onCleanup(@()detecdiv_close_progress(d)); %#ok<NASGU>
                drawnow nocallbacks;
            catch
            end
            try
                [app.CurrentProject, report] = detecdiv_paths_relink_project(app.CurrentProject, root, ...
                    'Force', true, 'Debug', false);
                okCount = 0;
                changedCount = 0;
                try
                    okCount = sum([report.ok]);
                    changedCount = sum(~strcmp({report.before}, {report.after}));
                catch
                end
                if ~isempty(d), d.Message = 'Saving project after relink...'; end
                shallowSave(app.CurrentProject, 'shallowObj');
                rawPath = projectSourcePath(app, app.CurrentProject);
                if isempty(rawPath)
                    rawPath = char(string(root));
                end
                setRuntimeControlValue(app, 'rawDataPath', rawPath);
                app.RuntimeValues.rawDataPath = rawPath;
                app.RuntimeValues.rawDataPathActive = rawPath;
                clearRuntimeDataSeriesCache(app);
                updateRuntimeResourceInventory(app);
                refreshValidationReport(app, false);
                setRuntimeStatus(app, sprintf('Raw paths relinked: %d/%d entries ready, %d changed.', ...
                    okCount, numel(report), changedCount));
                try
                    uialert(app.UIFigure, sprintf('Raw data paths relinked.\n\nReady entries: %d/%d\nChanged entries: %d', ...
                        okCount, numel(report), changedCount), 'Relink raw data', 'Icon', 'success');
                catch
                end
            catch ME
                printExceptionToConsole(app, 'Raw data relink failed', ME);
                uialert(app.UIFigure, ME.message, 'Relink raw data', 'Icon', 'error');
            end
            try, close(d); catch, end
        end

        function items = projectDropdownItems(app)
            if runtimeStartsFromClassifier(app)
                choices = collectRuntimeClassifierChoices(app);
                items = {'Select source classifier...'};
                if ~isempty(choices)
                    items = [items {choices.label}]; %#ok<AGROW>
                end
                return;
            end
            items = {'Select project...'};
            choices = workspaceShallowProjectChoices(app);
            for i = 1:size(choices, 1)
                items{end+1} = choices{i,1}; %#ok<AGROW>
            end
            items = [items {'New project from scratch...'}];
        end

        function choices = workspaceShallowProjectChoices(app) %#ok<INUSD>
            choices = cell(0, 2);
            try
                vars = evalin('base', 'who');
            catch
                vars = {};
            end
            for i = 1:numel(vars)
                varName = vars{i};
                try
                    obj = evalin('base', varName);
                catch
                    continue;
                end
                if ~isa(obj, 'shallow')
                    continue;
                end
                label = varName;
                try
                    [pth, file] = obj.getPath;
                    if ~isempty(file)
                        label = sprintf('%s (%s)', varName, file);
                    end
                catch
                end
                choices(end+1,:) = {label, varName}; %#ok<AGROW>
            end
        end

        function refreshProjectDropdown(app)
            if ~isfield(app.RuntimeFieldHandles, 'projectSource') || ~isvalid(app.RuntimeFieldHandles.projectSource)
                return;
            end
            dd = app.RuntimeFieldHandles.projectSource;
            if runtimeStartsFromClassifier(app)
                choices = collectRuntimeClassifierChoices(app);
                app.RuntimeClassifierChoices = choices;
                labels = {'Select source classifier...'};
                if ~isempty(choices)
                    labels = [labels {choices.label}]; %#ok<AGROW>
                end
                preferred = labels{1};
                if isstruct(app.RuntimeClassifierSourceRef) && isfield(app.RuntimeClassifierSourceRef, 'label') && ...
                        ~isempty(strtrim(char(string(app.RuntimeClassifierSourceRef.label))))
                    preferred = char(string(app.RuntimeClassifierSourceRef.label));
                end
                setRuntimeDropDownItems(app, dd, labels, preferred);
                return;
            end
            old = char(string(dd.Value));
            projectItems = projectDropdownItems(app);
            preferred = '';
            if ~isempty(app.CurrentProjectVarName)
                choices = workspaceShallowProjectChoices(app);
                idx = find(strcmp(choices(:,2), char(string(app.CurrentProjectVarName))), 1);
                if ~isempty(idx)
                    preferred = choices{idx,1};
                end
            end
            if isempty(preferred) && any(strcmp(projectItems, old)), preferred = old; end
            setRuntimeDropDownItems(app, dd, projectItems, preferred);
        end

        function setRuntimeDropDownItems(app, dd, items, preferred) %#ok<INUSD>
            if nargin < 4 || isempty(preferred) || ~any(strcmp(items, preferred))
                preferred = items{1};
            end
            try, dd.ItemsData = {}; catch, end
            currentItems = cellstr(string(dd.Items));
            transitionItems = unique([currentItems(:)' items(:)'], 'stable');
            dd.Items = transitionItems;
            dd.Value = preferred;
            dd.Items = items;
        end

        function projectDropdownChanged(app, value)
            value = char(string(value));
            if runtimeStartsFromClassifier(app)
                classifierSourceDropdownChanged(app, value);
                return;
            end
            if strcmp(value, 'Select project...')
                return;
            elseif strcmp(value, 'Browse existing...')
                chooseExistingProject(app);
                refreshProjectDropdown(app);
                return;
            elseif strcmp(value, 'New project from scratch...')
                createNewProjectFromDialog(app);
                refreshProjectDropdown(app);
                return;
            end

            choices = workspaceShallowProjectChoices(app);
            idx = find(strcmp(choices(:,1), value), 1);
            if isempty(idx)
                return;
            end
            varName = choices{idx,2};
            d = openRuntimeProgress(app, 'Project', 'Loading selected project metadata...');
            try
                drawnow limitrate;
                shallowObj = evalin('base', varName);
                updateRuntimeProgress(app, d, 'Refreshing FOV, ROI, channel and dataseries inventory...');
                bindCurrentProject(app, shallowObj, varName);
            catch ME
                closeRuntimeProgress(app, d);
                d = [];
                uialert(app.UIFigure, ME.message, 'Project selection', 'Icon', 'warning');
            end
            closeRuntimeProgress(app, d);
        end

        function choices = collectRuntimeClassifierChoices(app)
            choices = struct('key', {}, 'label', {}, 'kind', {}, 'path', {}, 'id', {}, 'object', {});
            try
                vars = evalin('base', 'whos');
            catch
                vars = struct('name', {}, 'class', {});
            end
            for i = 1:numel(vars)
                varName = char(string(vars(i).name));
                cls = lower(char(string(vars(i).class)));
                if ~any(strcmp(cls, {'classi','shallow'}))
                    continue;
                end
                try
                    obj = evalin('base', varName);
                catch
                    continue;
                end
                if isa(obj, 'classi')
                    for j = 1:numel(obj)
                        choice = makeRuntimeClassifierChoice(app, obj(j), ...
                            sprintf('workspace:%s:%d', varName, j), 'workspace_classi');
                        choice.label = sprintf('%s / %s', varName, choice.label);
                        choices(end+1) = choice; %#ok<AGROW>
                    end
                elseif isa(obj, 'shallow')
                    for pj = 1:numel(obj)
                        classList = projectClassifierList(app, obj(pj));
                        for j = 1:numel(classList)
                            choice = makeRuntimeClassifierChoice(app, classList(j), ...
                                sprintf('project:%s:%d:%d', varName, pj, j), 'workspace_project');
                            choice.label = sprintf('%s / %s', varName, choice.label);
                            choices(end+1) = choice; %#ok<AGROW>
                        end
                    end
                end
            end
            if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow') && isempty(app.CurrentProjectVarName)
                classList = projectClassifierList(app, app.CurrentProject);
                for j = 1:numel(classList)
                    choice = makeRuntimeClassifierChoice(app, classList(j), ...
                        sprintf('current_project:%d', j), 'current_project');
                    choice.label = ['current project / ' choice.label];
                    choices(end+1) = choice; %#ok<AGROW>
                end
            end
            currentKey = '';
            if isstruct(app.RuntimeClassifierSourceRef) && isfield(app.RuntimeClassifierSourceRef, 'key')
                currentKey = char(string(app.RuntimeClassifierSourceRef.key));
            end
            if ~isempty(app.RuntimeClassifierSource) && isa(app.RuntimeClassifierSource, 'classi') && ...
                    ~isempty(currentKey) && (isempty(choices) || ~any(strcmp({choices.key}, currentKey)))
                choice = makeRuntimeClassifierChoice(app, app.RuntimeClassifierSource, currentKey, ...
                    char(string(app.RuntimeClassifierSourceRef.kind)));
                choice.label = char(string(app.RuntimeClassifierSourceRef.label));
                if isempty(choice.label)
                    choice.label = classifierSourceLabel(app, app.RuntimeClassifierSource);
                end
                choices(end+1) = choice; %#ok<AGROW>
            end
            if ~isempty(choices)
                signatures = cell(1, numel(choices));
                for i = 1:numel(choices)
                    signatures{i} = [lower(char(string(choices(i).path))) '|' lower(char(string(choices(i).id)))];
                end
                [~, keep] = unique(signatures, 'stable');
                choices = choices(sort(keep));
            end
        end

        function classList = projectClassifierList(app, shallowObj) %#ok<INUSD>
            classList = [];
            try
                if isprop(shallowObj, 'processing') && isstruct(shallowObj.processing) && ...
                        isfield(shallowObj.processing, 'classification')
                    classList = shallowObj.processing.classification;
                end
            catch
                classList = [];
            end
            try
                keep = arrayfun(@(x) isa(x, 'classi') && isvalid(x), classList);
                classList = classList(keep);
            catch
                classList = [];
            end
        end

        function choice = makeRuntimeClassifierChoice(app, classiObj, key, kind)
            choice = struct('key', char(string(key)), 'label', classifierSourceLabel(app, classiObj), ...
                'kind', char(string(kind)), 'path', '', 'id', '', 'object', classiObj);
            try, choice.path = char(string(classiObj.path)); catch, end
            try, choice.id = char(string(classiObj.strid)); catch, end
        end

        function label = classifierSourceLabel(app, classiObj) %#ok<INUSD>
            id = 'classifier';
            nRoi = 0;
            try
                if ~isempty(classiObj.strid), id = char(string(classiObj.strid)); end
            catch
            end
            try, nRoi = numel(usableClassifierRois(app, classiObj)); catch, end
            label = sprintf('%s (%d ROI)', id, nRoi);
        end

        function classifierSourceDropdownChanged(app, value)
            if isempty(value)
                return;
            end
            choices = app.RuntimeClassifierChoices;
            if isempty(choices)
                return;
            end
            idx = find(strcmp({choices.label}, value), 1);
            if isempty(idx)
                return;
            end
            bindRuntimeClassifierSource(app, choices(idx).object, choices(idx));
        end

        function bindRuntimeClassifierSource(app, classiObj, choice)
            if isempty(classiObj) || ~isa(classiObj, 'classi')
                error('pipeline2:InvalidRoiSourceClassifier', 'The ROI source must be a classi object.');
            end
            if numel(classiObj) > 1
                classiObj = classiObj(1);
            end
            app.RuntimeClassifierSource = classiObj;
            ref = choice;
            if isfield(ref, 'object'), ref = rmfield(ref, 'object'); end
            app.RuntimeClassifierSourceRef = ref;
            app.ExplicitRuntimeRoiList = usableClassifierRois(app, classiObj);
            try, app.RuntimeFieldHandles.projectPath.Value = char(string(ref.label)); catch, end
            if isempty(app.ExplicitRuntimeRoiList)
                app.RuntimeValues.rois = '';
                setRuntimeControlValue(app, 'rois', '');
            else
                app.RuntimeValues.rois = sprintf('1:%d', numel(app.ExplicitRuntimeRoiList));
                setRuntimeControlValue(app, 'rois', app.RuntimeValues.rois);
            end
            channels = explicitRuntimeRoiChannels(app);
            if isempty(channels)
                if isfield(app.RuntimeParseInfo, 'channels')
                    app.RuntimeParseInfo = rmfield(app.RuntimeParseInfo, 'channels');
                end
            else
                app.RuntimeParseInfo.channels = channels;
            end
            clearRuntimeDataSeriesCache(app);
            markRunDirty(app, true);
            updateRuntimeResourceInventory(app);
            updateRuntimeInputStates(app);
            redrawGraph(app);
            refreshValidationReport(app, false);
            refreshModuleTabs(app);
        end

        function rois = usableClassifierRois(app, classiObj) %#ok<INUSD>
            rois = [];
            try, rois = classiObj.roi; catch, return; end
            if numel(rois) == 1
                isPlaceholder = false;
                try
                    isPlaceholder = isempty(rois.id) && isempty(rois.value) && ...
                        isempty(rois.image) && isempty(rois.path);
                catch
                end
                if isPlaceholder, rois = []; end
            end
        end

        function channels = explicitRuntimeRoiChannels(app)
            channels = {};
            rois = app.ExplicitRuntimeRoiList;
            maxRoi = min(numel(rois), 24);
            for i = 1:maxRoi
                try
                    if isprop(rois(i), 'display') && isstruct(rois(i).display) && ...
                            isfield(rois(i).display, 'channel')
                        channels = [channels cellstr(string(rois(i).display.channel(:)'))]; %#ok<AGROW>
                    end
                catch
                end
                % Include channels stored on disk even when the lightweight
                % ROI snapshot has not loaded their display metadata.
                channels = [channels roiStoredChannelNames(app, rois(i))]; %#ok<AGROW>
            end
            channels = cellfun(@(x) strtrim(char(string(x))), channels, 'UniformOutput', false);
            channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
        end

        function chooseExistingProject(app)
            [file, pth] = uigetfile({'*.json;*.mat','DetecDiv project (*.json, *.mat)'; ...
                '*.json','DetecDiv project manifest (*.json)'; '*.mat','Legacy shallow project (*.mat)'; ...
                '*.*','All files'}, ...
                'Select existing DetecDiv shallow project');
            if isequal(file, 0)
                pth = uigetdir(pwd, 'Select existing DetecDiv project folder');
                if isequal(pth, 0)
                    return;
                end
                target = pth;
            else
                target = fullfile(pth, file);
            end
            bindProjectFromPath(app, target, true);
        end

        function createNewProjectFromDialog(app)
            [file, pth] = uiputfile('*.json', 'Create DetecDiv shallow project', fullfile(pwd, 'new_project.json'));
            if isequal(file, 0)
                return;
            end
            [~, name, ext] = fileparts(file);
            if isempty(ext)
                file = [file '.json']; %#ok<NASGU>
            end
            projectFolder = fullfile(pth, name);
            existingJson = fullfile(pth, [name '.json']);
            existingMat = fullfile(pth, [name '.mat']);
            if isfile(existingJson) || isfile(existingMat) || isfolder(projectFolder)
                uialert(app.UIFigure, ...
                    sprintf(['A project named "%s" already exists here.\n\n' ...
                    'Use "Select project" / "Browse existing" to load it, or choose a new name.'], name), ...
                    'Project already exists', 'Icon', 'warning');
                return;
            end
            if ~exist(projectFolder, 'dir')
                mkdir(projectFolder);
            end
            shallowObj = shallow();
            shallowObj.setPath(ensureTrailingFilesep(app, pth), name);
            if ~isfield(shallowObj.processing, 'pipelineRun') || isempty(shallowObj.processing.pipelineRun)
                shallowObj.processing.pipelineRun = pipelineRun.empty;
            end
            shallowSave(shallowObj, 'shallowObj');
            varName = matlab.lang.makeValidName(name);
            assignin('base', varName, shallowObj);
            bindCurrentProject(app, shallowObj, varName);
        end

        function bindProjectFromPath(app, inputPath, showWarnings)
            if nargin < 3
                showWarnings = true;
            end
            inputPath = strtrim(char(string(inputPath)));
            if isempty(inputPath)
                return;
            end
            reportPipelineOpenStage(app, 'Locating the saved project...', inputPath);
            projectFilePath = resolveProjectFilePath(app, inputPath);
            if isempty(projectFilePath) || exist(projectFilePath, 'file') ~= 2
                if showWarnings
                    uialert(app.UIFigure, ['Project JSON or legacy MAT not found: ' inputPath], 'Project', 'Icon', 'warning');
                end
                return;
            end
            d = openRuntimeProgress(app, 'Project', 'Loading DetecDiv project...');
            try
                drawnow limitrate;
                reportPipelineOpenStage(app, 'Reading project metadata...', projectFilePath);
                [shallowObj, msg] = shallowLoad(projectFilePath);
                if isempty(shallowObj)
                    error('pipeline2:ProjectLoadFailed', '%s', msg);
                end
                [~, name] = fileparts(projectFilePath);
                varName = matlab.lang.makeValidName(name);
                assignin('base', varName, shallowObj);
                updateRuntimeProgress(app, d, 'Refreshing FOV, ROI, channel and dataseries inventory...');
                bindCurrentProject(app, shallowObj, varName);
            catch ME
                closeRuntimeProgress(app, d);
                d = [];
                if showWarnings
                    uialert(app.UIFigure, ME.message, 'Project', 'Icon', 'warning');
                end
            end
            closeRuntimeProgress(app, d);
        end

        function reportPipelineOpenStage(app, messageText, sourcePath)
            if ~app.OpeningPipeline, return; end
            if nargin >= 3 && ~isempty(sourcePath)
                messageText = sprintf(['%s\n%s\n\n' ...
                    'Waiting for file access. Network storage may take longer.'], ...
                    messageText, char(string(sourcePath)));
            end
            updateRuntimeProgress(app, [], messageText);
            if nargin >= 3 && ~isempty(sourcePath)
                % Flush before a potentially blocking filesystem operation;
                % keep ordinary local UI updates throttled.
                drawnow nocallbacks;
            end
        end

        function d = openRuntimeProgress(app, titleText, messageText)
            d = [];
            try
                active = [];
                try
                    active = app.RuntimeProgressDialog;
                catch
                    active = [];
                end
                if ~isempty(active) && isvalid(active)
                    active.Message = messageText;
                    setRuntimeStatus(app, messageText);
                    drawnow nocallbacks;
                    return;
                end
                d = uiprogressdlg(app.UIFigure, ...
                    'Title', titleText, ...
                    'Message', messageText, ...
                    'Indeterminate', 'on');
                app.RuntimeProgressDialog = d;
                setRuntimeStatus(app, messageText);
                drawnow nocallbacks;
            catch
                d = [];
            end
        end

        function updateRuntimeProgress(app, d, messageText) %#ok<INUSD>
            if app.OpeningPipeline && strcmp(getenv('DETECDIV_PIPELINE_OPEN_TRACE'), '1')
                fprintf('[pipeline2/open] %s\n', char(string(messageText)));
            end
            setRuntimeStatus(app, messageText);
            try
                target = d;
                if isempty(target) || ~isvalid(target)
                    target = app.RuntimeProgressDialog;
                end
                if ~isempty(target) && isvalid(target)
                    target.Message = messageText;
                    drawnow limitrate nocallbacks;
                end
            catch
            end
        end

        function closeRuntimeProgress(app, d) %#ok<INUSD>
            try
                if ~isempty(d) && isvalid(d)
                    close(d);
                    try
                        if isequal(app.RuntimeProgressDialog, d)
                            app.RuntimeProgressDialog = [];
                        end
                    catch
                        app.RuntimeProgressDialog = [];
                    end
                end
            catch
            end
        end

        function projectFilePath = resolveProjectFilePath(app, inputPath) %#ok<INUSD>
            projectFilePath = '';
            inputPath = char(string(inputPath));
            if exist(inputPath, 'file') == 2
                [~, ~, ext] = fileparts(inputPath);
                if any(strcmpi(ext, {'.json','.mat'}))
                    projectFilePath = inputPath;
                end
                return;
            end
            [inputFolder, inputName, inputExt] = fileparts(inputPath);
            if any(strcmpi(inputExt, {'.json','.mat'}))
                jsonSibling = fullfile(inputFolder, [inputName '.json']);
                matSibling = fullfile(inputFolder, [inputName '.mat']);
                if exist(jsonSibling, 'file') == 2
                    projectFilePath = jsonSibling;
                elseif exist(matSibling, 'file') == 2
                    projectFilePath = matSibling;
                end
                return;
            end
            if exist(inputPath, 'dir') ~= 7
                return;
            end
            [parentPath, folderName] = fileparts(inputPath);
            jsonCandidate = fullfile(parentPath, [folderName '.json']);
            if exist(jsonCandidate, 'file') == 2
                projectFilePath = jsonCandidate;
                return;
            end
            matCandidate = fullfile(parentPath, [folderName '.mat']);
            if exist(matCandidate, 'file') == 2
                projectFilePath = matCandidate;
                return;
            end
            d = dir(fullfile(inputPath, '*.json'));
            if numel(d) == 1
                projectFilePath = fullfile(d(1).folder, d(1).name);
                return;
            end
            d = dir(fullfile(inputPath, '*.mat'));
            if numel(d) == 1
                projectFilePath = fullfile(d(1).folder, d(1).name);
            end
        end

        function bindCurrentProject(app, shallowObj, varName)
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end
            beginModuleTabRefreshBatch(app);
            tabBatchCleanup = onCleanup(@()endModuleTabRefreshBatch(app, true)); %#ok<NASGU>
            app.CurrentProject = shallowObj;
            app.CurrentProjectVarName = char(string(varName));
            if ~app.OpeningPipeline
                app.SavedRuntimeInventoryContext = struct();
                app.RuntimeInventoryMetadataOnly = true;
            end
            clearRuntimeDataSeriesCache(app);
            app.RuntimeInventoryRefreshSuspended = true;
            cleanupObj = onCleanup(@()setRuntimeInventoryRefreshSuspended(app, false)); %#ok<NASGU>
            [pth, file] = shallowObj.getPath;
            projectFilePath = fullfile(pth, [file '.json']);
            if ~app.OpeningPipeline && exist(projectFilePath, 'file') ~= 2
                legacyMatPath = fullfile(pth, [file '.mat']);
                if exist(legacyMatPath, 'file') == 2
                    projectFilePath = legacyMatPath;
                end
            end
            setRuntimeValuePreserveParse(app, 'projectPath', projectFilePath);
            if runtimeStartsFromExistingProject(app)
                refreshRuntimeFromProject(app);
            else
                refreshRawRuntimeAfterProjectBind(app);
            end
            app.RuntimeInventoryRefreshSuspended = false;
            updateRuntimeResourceInventory(app);
            refreshProjectDropdown(app);
            updateRuntimeInputStates(app);
            redrawGraph(app);
            refreshModuleTabs(app);
            refreshValidationReport(app, false);
        end

        function refreshRawRuntimeAfterProjectBind(app)
            rawPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
            if isempty(rawPath) && isfield(app.RuntimeValues, 'rawDataPathActive')
                rawPath = strtrim(char(string(app.RuntimeValues.rawDataPathActive)));
            end
            if isempty(rawPath)
                return;
            end
            setRuntimeControlValue(app, 'rawDataPath', rawPath);
            app.RuntimeValues.rawDataPath = rawPath;
            app.RuntimeValues.rawDataPathActive = rawPath;
            if isfield(app.RuntimeParseInfo, 'path') && strcmp(char(string(app.RuntimeParseInfo.path)), rawPath) && ...
                    isfield(app.RuntimeParseInfo, 'ok') && app.RuntimeParseInfo.ok
                applyRuntimeParseInfo(app, app.RuntimeParseInfo);
            else
                parseRuntimeRawDataPath(app, rawPath);
            end
        end

        function setRuntimeInventoryRefreshSuspended(app, value)
            app.RuntimeInventoryRefreshSuspended = logical(value);
        end

        function refreshRuntimeFromProject(app)
            shallowObj = app.CurrentProject;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end
            channels = {};
            try
                fovs = shallowObj.fov;
                for fovIndex = 1:numel(fovs)
                    try
                        if isprop(fovs(fovIndex), 'channel') && ...
                                ~isempty(fovs(fovIndex).channel)
                            channels = [channels normalizeChannelCellText( ...
                                app, fovs(fovIndex).channel)]; %#ok<AGROW>
                        end
                    catch
                    end
                end
            catch
            end
            channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
            updateChannelDropdownItems(app, channels);
        end

        function sourcePath = projectSourcePath(app, shallowObj) %#ok<INUSD>
            sourcePath = '';
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end
            try
                fovs = shallowObj.fov;
            catch
                fovs = [];
            end
            candidates = {};
            for i = 1:numel(fovs)
                candidates = [candidates fovSourcePathCandidates(app, fovs(i), 1)]; %#ok<AGROW>
                if ~isempty(candidates)
                    break;
                end
            end
            candidates = normalizeSourcePathCandidates(app, candidates);
            candidates = unique(candidates(~cellfun(@isempty, candidates)), 'stable');
            if isempty(candidates)
                return;
            end
            sourcePath = candidates{1};
        end

        function tf = projectHasFovImageSources(app, shallowObj)
            tf = false;
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end
            try
                fovs = shallowObj.fov;
            catch
                fovs = [];
            end
            for i = 1:numel(fovs)
                hasChannels = false;
                try
                    hasChannels = isprop(fovs(i), 'channel') && ~isempty(fovs(i).channel);
                catch
                end
                if ~hasChannels
                    continue;
                end
                % This is an existence-in-metadata check, not an inventory.
                % One saved source is sufficient, even for very long movies.
                candidates = fovSourcePathCandidates(app, fovs(i), 1);
                candidates = normalizeSourcePathCandidates(app, candidates);
                candidates = candidates(~cellfun(@isempty, candidates));
                if ~isempty(candidates)
                    tf = true;
                    return;
                end
            end
        end

        function candidates = fovSourcePathCandidates(app, f, maxCandidates)
            if nargin < 3 || isempty(maxCandidates)
                maxCandidates = Inf;
            end
            candidates = {};
            candidates = [candidates sourcePathCandidatesFromProperty(app, f, 'omeZarrPath', false)]; %#ok<AGROW>
            if numel(candidates) >= maxCandidates, return; end
            candidates = [candidates sourcePathCandidatesFromProperty(app, f, 'ndtiffPath', false)]; %#ok<AGROW>
            if numel(candidates) >= maxCandidates, return; end
            candidates = [candidates sourcePathCandidatesFromProperty(app, f, 'srcpath', false)]; %#ok<AGROW>
            if numel(candidates) >= maxCandidates, return; end
            candidates = [candidates sourcePathCandidatesFromProperty(app, f, 'tiffSource', true)]; %#ok<AGROW>
            if numel(candidates) >= maxCandidates, return; end
            try
                if isprop(f, 'srclist') && iscell(f.srclist) && ~isempty(f.srclist)
                    for ch = 1:numel(f.srclist)
                        if isempty(f.srclist{ch})
                            continue;
                        end
                        remaining = maxCandidates - numel(candidates);
                        candidates = [candidates sourcePathCandidatesFromValue(app, f.srclist{ch}, true, remaining)]; %#ok<AGROW>
                        if numel(candidates) >= maxCandidates
                            return;
                        end
                    end
                end
            catch
            end
        end

        function candidates = sourcePathCandidatesFromProperty(app, obj, propName, valueIsFile)
            candidates = {};
            try
                if ~isprop(obj, propName) || isempty(obj.(propName))
                    return;
                end
                candidates = sourcePathCandidatesFromValue(app, obj.(propName), valueIsFile);
            catch
                candidates = {};
            end
        end

        function candidates = sourcePathCandidatesFromValue(app, value, valueIsFile, maxCandidates) %#ok<INUSD>
            if nargin < 4 || isempty(maxCandidates)
                maxCandidates = Inf;
            end
            candidates = {};
            if isempty(value) || maxCandidates <= 0
                return;
            end
            if iscell(value)
                for i = 1:numel(value)
                    remaining = maxCandidates - numel(candidates);
                    if remaining <= 0
                        break;
                    end
                    candidates = [candidates sourcePathCandidatesFromValue(app, value{i}, valueIsFile, remaining)]; %#ok<AGROW>
                end
                return;
            end
            if ischar(value)
                if isrow(value)
                    values = {value};
                else
                    values = cellstr(value);
                end
                for i = 1:numel(values)
                    item = strtrim(values{i});
                    if isempty(item)
                        continue;
                    end
                    candidates{end+1} = normalizeSourcePathValue(app, item, valueIsFile); %#ok<AGROW>
                    if numel(candidates) >= maxCandidates
                        break;
                    end
                end
                return;
            end
            if isstring(value)
                values = cellstr(value(:));
                for i = 1:numel(values)
                    item = strtrim(values{i});
                    if isempty(item)
                        continue;
                    end
                    candidates{end+1} = normalizeSourcePathValue(app, item, valueIsFile); %#ok<AGROW>
                    if numel(candidates) >= maxCandidates
                        break;
                    end
                end
                return;
            end
            if isstruct(value)
                for i = 1:numel(value)
                    if isfield(value, 'folder') && isfield(value, 'name') && ~isempty(value(i).folder)
                        item = fullfile(char(string(value(i).folder)), char(string(value(i).name)));
                    elseif isfield(value, 'name') && ~isempty(value(i).name)
                        item = char(string(value(i).name));
                    else
                        continue;
                    end
                    candidates{end+1} = normalizeSourcePathValue(app, item, true); %#ok<AGROW>
                    if numel(candidates) >= maxCandidates
                        break;
                    end
                end
                return;
            end
            try
                item = strtrim(char(string(value)));
                if ~isempty(item)
                    candidates{end+1} = normalizeSourcePathValue(app, item, valueIsFile); %#ok<AGROW>
                end
            catch
            end
        end

        function item = normalizeSourcePathValue(app, item, valueIsFile) %#ok<INUSD>
            item = strtrim(char(string(item)));
            if isempty(item)
                return;
            end
            if valueIsFile
                if endsWith(item, filesep) || endsWith(item, '/') || endsWith(item, '\')
                    return;
                end
                [pth, ~, ext] = fileparts(item);
                if ~isempty(pth) && ~isempty(ext)
                    item = pth;
                end
            end
        end

        function candidates = normalizeSourcePathCandidates(app, raw) %#ok<INUSD>
            candidates = {};
            if isempty(raw)
                return;
            end
            for i = 1:numel(raw)
                item = raw{i};
                if isempty(item)
                    continue;
                end
                if iscell(item)
                    sub = normalizeSourcePathCandidates(app, item);
                    candidates = [candidates sub]; %#ok<AGROW>
                elseif ischar(item)
                    if isrow(item)
                        candidates{end+1} = strtrim(item); %#ok<AGROW>
                    elseif ismatrix(item)
                        vals = cellstr(item);
                        vals = cellfun(@strtrim, vals, 'UniformOutput', false);
                        candidates = [candidates vals(:)']; %#ok<AGROW>
                    end
                elseif isstring(item)
                    vals = cellstr(item(:));
                    vals = cellfun(@strtrim, vals, 'UniformOutput', false);
                    candidates = [candidates vals(:)']; %#ok<AGROW>
                else
                    try
                        candidates{end+1} = strtrim(char(string(item))); %#ok<AGROW>
                    catch
                    end
                end
            end
        end

        function out = ensureTrailingFilesep(app, pth) %#ok<INUSD>
            out = char(string(pth));
            if isempty(out)
                return;
            end
            if ~endsWith(out, filesep)
                out = [out filesep];
            end
        end

        function showOutputPolicyHelp(app)
            msg = [ ...
                "Resume options control progress checkpoints." newline ...
                "Output policy controls what happens when output files/data already exist." newline newline ...
                "Recommended default:" newline ...
                "- Resume previous progress + Skip existing outputs" newline newline ...
                "Full rerun:" newline ...
                "- Restart from scratch + Replace existing outputs" newline newline ...
                "Append/update is for partial ROI extraction continuation or controlled upserts."];
            uialert(app.UIFigure, msg, 'Run policy', 'Icon', 'info');
        end

        function markOutputPolicyUserChosen(app)
            app.RuntimeValues.outputPolicyUserChosen = true;
        end

        function tf = isOutputPolicyUserChosen(app)
            tf = false;
            if isfield(app.RuntimeValues, 'outputPolicyUserChosen') && ~isempty(app.RuntimeValues.outputPolicyUserChosen)
                tf = logical(app.RuntimeValues.outputPolicyUserChosen);
            end
        end

        function applyRecommendedOutputPolicyForResume(app)
            if ~isfield(app.RuntimeFieldHandles, 'outputPolicy') || ~isvalid(app.RuntimeFieldHandles.outputPolicy)
                return;
            end
            resumeMode = char(string(app.ResumeoptionsDropDown.Value));
            current = getRuntimeValue(app, 'outputPolicy');
            recommended = recommendedOutputPolicy(app, resumeMode);

            shouldAutoSet = isempty(current) || ...
                (~isOutputPolicyUserChosen(app) && ~strcmp(current, recommended)) || ...
                (strcmpi(resumeMode, 'Restart from scratch') && strcmp(current, 'skip')) || ...
                (strcmpi(resumeMode, 'Resume previous progress') && strcmp(current, 'replace'));
            if shouldAutoSet
                app.RuntimeValues.outputPolicyUserChosen = false;
                setRuntimeValuePreserveParse(app, 'outputPolicy', recommended);
            end
        end

        function policy = recommendedOutputPolicy(app, resumeMode) %#ok<INUSD>
            if strcmpi(char(string(resumeMode)), 'Restart from scratch')
                policy = 'replace';
            else
                policy = 'skip';
            end
        end

        function setRuntimeValue(app, key, value)
            value = char(string(value));
            app.RuntimeValues.(key) = value;
            if isfield(app.RuntimeFieldHandles, key) && isvalid(app.RuntimeFieldHandles.(key))
                setRuntimeControlValue(app, key, value);
            end
            syncRuntimeValueToNodeParams(app, key);
            if strcmp(char(string(key)), 'rawDataPath')
                if ~runtimeStartsFromExistingProject(app)
                    app.RuntimeValues.rawDataPathActive = value;
                end
                parseRuntimeRawDataPath(app, value);
            end
            if strcmp(char(string(key)), 'outputPolicy')
                markOutputPolicyUserChosen(app);
            end
            updateRuntimeInputStates(app);
            refreshValidationReport(app, false);
        end

        function runtimeRunIdChanged(app, value)
            runId = matlab.lang.makeValidName(strtrim(char(string(value))));
            app.RuntimeValues.runId = runId;
            markRunDirty(app, true);
            try
                app.TemplateidEditField.Value = runId;
            catch
            end
            if ~isempty(app.CurrentRun) && isa(app.CurrentRun, 'pipelineRun')
                try
                    currentId = char(string(app.CurrentRun.runId));
                    if ~strcmp(currentId, runId)
                        app.CurrentRunIsSeed = true;
                    end
                catch
                end
            end
            if isempty(runId)
                setRuntimeStatus(app, sprintf('Run draft renamed.\nEnter a run id before saving or launching.'));
            elseif app.CurrentRunIsSeed
                setRuntimeStatus(app, sprintf('Run draft renamed: %s\nRun/Save will create a distinct run with this id.', runId));
            else
                setRuntimeStatus(app, sprintf('Run id: %s\nRun/Save will use this id.', runId));
            end
            updatePipelineWindowTitle(app);
            updatePipelineRunStatusBar(app);
        end

        function value = getRuntimeValue(app, key)
            value = '';
            % Selection edit fields are authoritative at launch time. A
            % user can type a value and immediately press Run before the
            % ValueChanged callback has copied it into RuntimeValues.
            % Reading the cache first silently turned that visible subset
            % into an empty ("all") selection in the worker payload.
            selectionKey = any(strcmp(char(string(key)), {'fovs','frames','rois'}));
            if selectionKey && isfield(app.RuntimeFieldHandles, key) && ...
                    isvalid(app.RuntimeFieldHandles.(key))
                value = char(string(app.RuntimeFieldHandles.(key).Value));
            elseif isfield(app.RuntimeValues, key)
                value = char(string(app.RuntimeValues.(key)));
            elseif isfield(app.RuntimeFieldHandles, key) && isvalid(app.RuntimeFieldHandles.(key))
                value = char(string(app.RuntimeFieldHandles.(key).Value));
            end
        end

        function setRuntimeExecutionTarget(app, target)
            target = lower(strtrim(char(string(target))));
            target = strrep(target, '-', '_');
            target = strrep(target, ' ', '_');
            if isempty(target)
                target = 'local';
            elseif any(strcmp(target, {'windows','local_windows','local_matlab','local/windowspython'}))
                target = 'local';
            elseif any(strcmp(target, {'local_wsl','wsl','localwsl','local_linux','local/wsl'}))
                target = 'local_wsl';
            end
            try
                if isstruct(app.HubFieldHandles) && isfield(app.HubFieldHandles, 'executionTarget') && ...
                        isvalid(app.HubFieldHandles.executionTarget)
                    ctrl = app.HubFieldHandles.executionTarget;
                    if ~isempty(ctrl.ItemsData) && any(strcmp(ctrl.ItemsData, target))
                        ctrl.Value = target;
                    elseif any(strcmp(ctrl.Items, target))
                        ctrl.Value = target;
                    end
                end
            catch
            end
            app.RuntimeValues.executionTarget = target;
            try
                updateHubRuntimeControlsVisibility(app);
            catch
            end
        end

        function setRuntimeControlValue(app, key, value)
            if ~isfield(app.RuntimeFieldHandles, key) || ~isvalid(app.RuntimeFieldHandles.(key))
                return;
            end
            ctrl = app.RuntimeFieldHandles.(key);
            value = char(string(value));
            try
                if isa(ctrl, 'matlab.ui.control.DropDown')
                    if isempty(value)
                        return;
                    end
                    if ~isempty(ctrl.ItemsData)
                        if ~any(strcmp(ctrl.ItemsData, value))
                            return;
                        end
                    elseif ~any(strcmp(ctrl.Items, value))
                        ctrl.Items = [ctrl.Items {value}];
                    end
                elseif isa(ctrl, 'matlab.ui.control.TextArea')
                    ctrl.Value = cellstr(splitlines(string(value)));
                    return;
                end
                ctrl.Value = value;
            catch
            end
        end

        function parseRuntimeRawDataPath(app, rawDataPath)
            if app.OpeningPipeline, return; end
            rawDataPath = strtrim(char(string(rawDataPath)));
            if isempty(rawDataPath) || ~(exist(rawDataPath, 'dir') == 7 || exist(rawDataPath, 'file') == 2)
                clearRuntimeParseInfo(app);
                return;
            end
            if isfield(app.RuntimeParseInfo, 'path') && strcmp(char(string(app.RuntimeParseInfo.path)), rawDataPath)
                if isfield(app.RuntimeParseInfo, 'ok') && app.RuntimeParseInfo.ok
                    applyRuntimeParseInfo(app, app.RuntimeParseInfo);
                    updateRuntimeResourceInventory(app);
                end
                return;
            end

            d = [];
            try
                d = uiprogressdlg(app.UIFigure, 'Title', 'Raw data parser', ...
                    'Message', 'Loading raw dataset metadata...', 'Indeterminate', 'on');
                ddProgressGuard2 = onCleanup(@()detecdiv_close_progress(d)); %#ok<NASGU>
                drawnow nocallbacks;
            catch
            end
            try
                updateRuntimeProgress(app, d, 'Parsing raw dataset metadata...');
                out = parseInputData(rawDataPath);
                updateRuntimeProgress(app, d, 'Refreshing FOV, frame and channel inventory...');
                info = summarizeParsedRawData(app, out, rawDataPath);
                app.RuntimeParseInfo = info;
                updateRuntimeProgress(app, d, 'Updating runtime inputs and module bindings...');
                applyRuntimeParseInfo(app, info);
            catch ME
                app.RuntimeParseInfo = struct('path', rawDataPath, 'ok', false, 'message', ME.message);
                try
                    uialert(app.UIFigure, ['Raw data parsing failed: ' ME.message], 'Raw data parser', 'Icon', 'warning');
                catch
                end
            end
            try, close(d); catch, end
        end

        function clearRuntimeParseInfo(app)
            app.RuntimeParseInfo = struct();
            updateChannelDropdownItems(app, {});
        end

        function info = summarizeParsedRawData(app, out, rawDataPath) %#ok<INUSD>
            info = struct('path', rawDataPath, 'ok', false, 'message', '', ...
                'datatype', '', 'fovCount', 0, 'fovNames', {{}}, 'maxFrame', [], 'channels', {{}});
            if isempty(out) || ~isstruct(out)
                info.message = 'No parser output.';
                return;
            end
            if isfield(out, 'datatype')
                info.datatype = char(string(out.datatype));
            end
            if ~isfield(out, 'pos') || isempty(out.pos)
                info.message = 'No positions detected.';
                return;
            end
            pos = out.pos;
            valid = true(1, numel(pos));
            for i = 1:numel(pos)
                valid(i) = isstruct(pos(i)) && ...
                    ((isfield(pos(i), 'name') && ~isempty(pos(i).name)) || ...
                    (isfield(pos(i), 'channelname') && ~isempty(pos(i).channelname)) || ...
                    (isfield(pos(i), 'frames') && ~isempty(pos(i).frames)));
            end
            pos = pos(valid);
            if isempty(pos)
                info.message = 'No valid positions detected.';
                return;
            end

            info.fovCount = numel(pos);
            names = cell(1, numel(pos));
            for i = 1:numel(pos)
                if isfield(pos(i), 'name') && ~isempty(pos(i).name)
                    names{i} = char(string(pos(i).name));
                else
                    names{i} = sprintf('FOV %d', i);
                end
            end
            info.fovNames = names;

            frames = [];
            for i = 1:numel(pos)
                if isfield(pos(i), 'frames') && ~isempty(pos(i).frames)
                    frames = [frames double(pos(i).frames(:)')]; %#ok<AGROW>
                elseif isfield(pos(i), 'srclist') && iscell(pos(i).srclist) && ~isempty(pos(i).srclist) && ~isempty(pos(i).srclist{1})
                    frames(end+1) = numel(pos(i).srclist{1}); %#ok<AGROW>
                end
            end
            frames = frames(isfinite(frames) & frames > 0);
            if ~isempty(frames)
                info.maxFrame = max(round(frames));
            end

            channels = {};
            for i = 1:numel(pos)
                if isfield(pos(i), 'channelname') && ~isempty(pos(i).channelname)
                    channels = [channels cellstr(string(pos(i).channelname(:)'))]; %#ok<AGROW>
                elseif isfield(pos(i), 'channels') && ~isempty(pos(i).channels)
                    nCh = max(1, round(double(pos(i).channels(1))));
                    channels = [channels arrayfun(@(k)sprintf('ch%d', k), 1:nCh, 'UniformOutput', false)]; %#ok<AGROW>
                end
            end
            info.channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
            info.ok = true;
        end

        function applyRuntimeParseInfo(app, info)
            if ~isstruct(info) || ~isfield(info, 'ok') || ~info.ok
                return;
            end
            beginModuleTabRefreshBatch(app);
            tabBatchCleanup = onCleanup(@()endModuleTabRefreshBatch(app, true)); %#ok<NASGU>
            if isfield(info, 'fovCount') && info.fovCount > 0
                setRuntimeValuePreserveParse(app, 'fovs', sprintf('1:%d', info.fovCount));
                try
                    app.RuntimeFieldHandles.fovs.Tooltip = sprintf('Detected %d FOV(s): %s', info.fovCount, strjoin(info.fovNames, ', '));
                catch
                end
            end
            if isfield(info, 'maxFrame') && ~isempty(info.maxFrame) && isfinite(info.maxFrame) && info.maxFrame > 0
                setRuntimeValuePreserveParse(app, 'frames', sprintf('1:%d', round(info.maxFrame)));
                try
                    app.RuntimeFieldHandles.frames.Tooltip = sprintf('Detected frames: 1:%d', round(info.maxFrame));
                catch
                end
            end
            if isfield(info, 'channels')
                updateChannelDropdownItems(app, info.channels);
            end
        end

        function setRuntimeValuePreserveParse(app, key, value)
            app.RuntimeValues.(key) = char(string(value));
            setRuntimeControlValue(app, key, value);
            if any(strcmp(char(string(key)), {'projectPath','fovs','rois'}))
                clearRuntimeDataSeriesCache(app);
            end
            if app.RuntimeInventoryRefreshSuspended
                return;
            end
            if any(strcmp(char(string(key)), {'projectPath','rawDataPath','fovs','rois'}))
                updateRuntimeResourceInventory(app);
            end
            if runtimeValueAffectsBindings(app, key)
                refreshModuleTabs(app);
            end
        end

        function updateChannelDropdownItems(app, channels)
            try
                channels = unique(cellstr(string(channels(:)')), 'stable');
                channels = channels(~cellfun(@isempty, channels));
                if isempty(channels)
                    if isfield(app.RuntimeParseInfo, 'channels')
                        app.RuntimeParseInfo = rmfield(app.RuntimeParseInfo, 'channels');
                    end
                    app.RuntimeValues.channels = '';
                    updateRuntimeResourceInventory(app);
                    refreshModuleTabs(app);
                    return;
                end
                app.RuntimeParseInfo.channels = channels;
                app.RuntimeValues.channels = '';
                updateRuntimeResourceInventory(app);
                refreshModuleTabs(app);
            catch
            end
        end

        function updateRuntimeResourceInventory(app)
            if ~isfield(app.RuntimeFieldHandles, 'availableResources') || ~isvalid(app.RuntimeFieldHandles.availableResources)
                return;
            end
            if runtimeStartsFromClassifier(app)
                intent = getRuntimeValue(app, 'intent');
                if isempty(strtrim(intent))
                    intent = 'infer';
                end
                roiText = getRuntimeValue(app, 'rois');
                if isempty(strtrim(roiText))
                    if ~isempty(app.ExplicitRuntimeRoiList)
                        roiText = sprintf('1:%d', numel(app.ExplicitRuntimeRoiList));
                    else
                        roiText = 'unresolved';
                    end
                end
                frameText = getRuntimeValue(app, 'frames');
                if isempty(strtrim(frameText))
                    frameText = 'all';
                end
                channelText = getRuntimeValue(app, 'channels');
                if isempty(strtrim(channelText))
                    sourceChannels = explicitRuntimeRoiChannels(app);
                    if isempty(sourceChannels)
                        channelText = 'classifier default';
                    else
                        channelText = strjoin(sourceChannels, ', ');
                    end
                end
                sourceLabel = 'not selected';
                if isstruct(app.RuntimeClassifierSourceRef) && isfield(app.RuntimeClassifierSourceRef, 'label') && ...
                        ~isempty(strtrim(char(string(app.RuntimeClassifierSourceRef.label))))
                    sourceLabel = char(string(app.RuntimeClassifierSourceRef.label));
                elseif app.RuntimeInputModeLocked && ~isempty(app.ExplicitRuntimeRoiList)
                    sourceLabel = 'classifierGUI launch context';
                end
                lines = {'Run summary: classifier attached ROIs'};
                lines{end+1} = ['Intent: ' intent];
                lines{end+1} = ['Source classifier: ' sourceLabel];
                lines{end+1} = sprintf('Classifier ROI inventory: %d ROI(s)', numel(app.ExplicitRuntimeRoiList));
                lines{end+1} = ['Selected source ROIs: ' roiText];
                lines{end+1} = ['Frames: ' frameText];
                lines{end+1} = ['Channels: ' channelText];
                if app.RuntimeInputModeLocked
                    lines{end+1} = 'Authority: classifierGUI train/test split';
                else
                    lines{end+1} = 'Authority: selected source classifier (ROI container only)';
                end
                lines{end+1} = 'Execution target: select on the Run tab';
                app.RuntimeFieldHandles.availableResources.Value = lines;
                return;
            end
            if ~runtimeStartsFromExistingProject(app)
                lines = {'Run summary: parse raw images into project'};
                projectPath = getRuntimeValue(app, 'projectPath');
                if isempty(strtrim(projectPath))
                    lines{end+1} = 'Writes to project: not selected yet';
                else
                    lines{end+1} = ['Writes to project: ' projectPath];
                end
                rawPath = getRuntimeValue(app, 'rawDataPath');
                if isempty(strtrim(rawPath))
                    lines{end+1} = 'Reads raw images from: not selected';
                    lines{end+1} = 'Authority: raw data parser';
                    lines{end+1} = 'FOVs: unresolved';
                    lines{end+1} = 'Frames: unresolved';
                    lines{end+1} = 'Channels: unresolved';
                    app.RuntimeFieldHandles.availableResources.Value = lines;
                    return;
                end
                lines{end+1} = ['Reads raw images from: ' rawPath];
                lines{end+1} = 'Authority: raw data parser';
                if isfield(app.RuntimeParseInfo, 'ok') && ~app.RuntimeParseInfo.ok
                    msg = '';
                    if isfield(app.RuntimeParseInfo, 'message')
                        msg = char(string(app.RuntimeParseInfo.message));
                    end
                    lines{end+1} = ['Parser status: failed' ternary(app, isempty(msg), '', [' - ' msg])];
                    app.RuntimeFieldHandles.availableResources.Value = lines;
                    return;
                end
                if isfield(app.RuntimeParseInfo, 'fovCount') && app.RuntimeParseInfo.fovCount > 0
                    lines{end+1} = sprintf('FOVs: 1:%d', round(double(app.RuntimeParseInfo.fovCount)));
                else
                    lines{end+1} = 'FOVs: unresolved';
                end
                if isfield(app.RuntimeParseInfo, 'maxFrame') && ~isempty(app.RuntimeParseInfo.maxFrame)
                    lines{end+1} = sprintf('Frames: 1:%d', round(double(app.RuntimeParseInfo.maxFrame)));
                else
                    lines{end+1} = 'Frames: unresolved';
                end
                channels = runtimeConcreteChannels(app);
                if isempty(channels)
                    lines{end+1} = 'Channels: none detected by raw parser';
                else
                    lines{end+1} = ['Channels: ' strjoin(channels, ', ')];
                end
                app.RuntimeFieldHandles.availableResources.Value = lines;
                return;
            end

            projectInfo = summarizeExistingProjectRuntime(app);
            channels = runtimeConcreteChannels(app);
            maskNames = runtimeMaskChoices(app);
            dataSeriesNames = {};
            try
                dataSeriesNames = runtimeDataSeriesNames(app);
            catch
                dataSeriesNames = {};
            end
            lines = {'Run summary: read from existing project'};
            projectPath = getRuntimeValue(app, 'projectPath');
            if ~isempty(strtrim(projectPath))
                lines{end+1} = ['Reads project data from: ' projectPath];
                lines{end+1} = ['Writes outputs to: ' projectPath];
            end
            projectHasImageSources = false;
            try
                projectHasImageSources = projectHasFovImageSources(app, app.CurrentProject);
            catch
            end
            lines{end+1} = 'Authority: selected project data';
            if isfield(projectInfo, 'rawDataPath') && ~isempty(projectInfo.rawDataPath)
                lines{end+1} = ['Raw data link: ' projectInfo.rawDataPath];
                rawStartNodeIds = selectedRunNodeIdsByType(app, {'dataloader','roigrid','roiidentify','roimanual','roipattern','roiextract'});
                if ~isempty(rawStartNodeIds)
                    if projectHasImageSources
                        lines{end+1} = 'Raw-image nodes: allowed, using image sources saved on the project FOVs.';
                    else
                        lines{end+1} = 'Raw-image nodes: blocked in this mode; switch to "Parse raw images into project".';
                    end
                end
            else
                lines{end+1} = 'Raw data link: not found in project';
            end
            if isfield(projectInfo, 'fovCount') && projectInfo.fovCount > 0
                fovSummary = sprintf('FOVs: 1:%d', projectInfo.fovCount);
                if isfield(projectInfo, 'fovNames') && ~isempty(projectInfo.fovNames)
                    names = projectInfo.fovNames;
                    defaultNames = arrayfun(@(k)sprintf('FOV %d', k), ...
                        1:numel(names), 'UniformOutput', false);
                    if ~isequal(names, defaultNames)
                        fovSummary = [fovSummary ' (IDs: ' strjoin(names, ', ') ')'];
                    end
                end
                lines{end+1} = fovSummary;
            else
                lines{end+1} = 'FOVs: none detected yet';
            end
            if isfield(projectInfo, 'maxFrame') && ~isempty(projectInfo.maxFrame) && projectInfo.maxFrame > 0
                lines{end+1} = sprintf('Frames: 1:%d', projectInfo.maxFrame);
            else
                lines{end+1} = 'Frames: unresolved';
            end
            if isfield(projectInfo, 'roiCount') && projectInfo.roiCount > 0
                lines{end+1} = sprintf('ROIs: %d existing ROI(s)', projectInfo.roiCount);
            else
                lines{end+1} = 'ROIs: none detected yet';
            end
            if isempty(channels)
                channelText = 'Channels: none detected yet';
            else
                channelText = ['Channels: ' strjoin(channels, ', ')];
            end
            if isempty(maskNames)
                maskText = 'Masks: none detected yet';
            else
                maskText = ['Masks: ' strjoin(maskNames, ', ')];
            end
            if isempty(dataSeriesNames)
                dsText = 'Data series: none detected yet';
            else
                maxShown = min(numel(dataSeriesNames), 12);
                dsText = ['Data series: ' strjoin(dataSeriesNames(1:maxShown), ', ')];
                if numel(dataSeriesNames) > maxShown
                    dsText = [dsText sprintf(' ... (+%d)', numel(dataSeriesNames) - maxShown)];
                end
                if isfield(app.RuntimeDataSeriesCache, 'sampledRoiCount') && app.RuntimeDataSeriesCache.sampledRoiCount > 0
                    dsText = [dsText sprintf(' (sampled %d ROI(s) across %d FOV(s))', ...
                        app.RuntimeDataSeriesCache.sampledRoiCount, ...
                        max(1, app.RuntimeDataSeriesCache.sampledFovCount))];
                end
            end
            app.RuntimeFieldHandles.availableResources.Value = [lines(:); {channelText; maskText; dsText}];
        end

        function info = summarizeExistingProjectRuntime(app)
            info = struct('fovCount', 0, 'fovNames', {{}}, 'maxFrame', [], 'roiCount', 0, 'rawDataPath', '');
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            info.rawDataPath = projectSourcePath(app, app.CurrentProject);
            try
                fovs = app.CurrentProject.fov;
            catch
                fovs = [];
            end
            if isempty(fovs)
                return;
            end
            info.fovCount = numel(fovs);
            names = cell(1, numel(fovs));
            frameCounts = [];
            roiCount = 0;
            for i = 1:numel(fovs)
                names{i} = sprintf('FOV %d', i);
                try
                    if isprop(fovs(i), 'id') && ~isempty(fovs(i).id)
                        names{i} = char(string(fovs(i).id));
                    elseif isprop(fovs(i), 'name') && ~isempty(fovs(i).name)
                        names{i} = char(string(fovs(i).name));
                    end
                catch
                end
                try
                    if isprop(fovs(i), 'roi') && ~isempty(fovs(i).roi)
                        roiCount = roiCount + numel(fovs(i).roi);
                    end
                catch
                end
                try
                    if isprop(fovs(i), 'srclist') && iscell(fovs(i).srclist) && ~isempty(fovs(i).srclist)
                        for ch = 1:numel(fovs(i).srclist)
                            if ~isempty(fovs(i).srclist{ch})
                                frameCounts(end+1) = numel(fovs(i).srclist{ch}); %#ok<AGROW>
                            end
                        end
                    end
                catch
                end
                try
                    if isprop(fovs(i), 'frames') && ~isempty(fovs(i).frames)
                        vals = double(fovs(i).frames(:)');
                        vals = vals(isfinite(vals) & vals > 0);
                        frameCounts = [frameCounts vals]; %#ok<AGROW>
                    end
                catch
                end
            end
            info.fovNames = names;
            info.roiCount = roiCount;
            frameCounts = frameCounts(isfinite(frameCounts) & frameCounts > 0);
            if ~isempty(frameCounts)
                info.maxFrame = max(round(frameCounts));
            end
        end

        function applied = applyExistingProjectRuntimeDefaults(app)
            applied = {};
            if ~runtimeStartsFromExistingProject(app) || isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            info = summarizeExistingProjectRuntime(app);
            if isfield(info, 'fovCount') && info.fovCount > 0 && isempty(strtrim(getRuntimeValue(app, 'fovs')))
                setRuntimeValuePreserveParse(app, 'fovs', sprintf('1:%d', info.fovCount));
                applied{end+1} = sprintf('FOVs=1:%d', info.fovCount); %#ok<AGROW>
            end
            if isfield(info, 'maxFrame') && ~isempty(info.maxFrame) && info.maxFrame > 0 && isempty(strtrim(getRuntimeValue(app, 'frames')))
                setRuntimeValuePreserveParse(app, 'frames', sprintf('1:%d', info.maxFrame));
                applied{end+1} = sprintf('Frames=1:%d', info.maxFrame); %#ok<AGROW>
            end
            if isfield(info, 'roiCount') && info.roiCount > 0 && isempty(strtrim(getRuntimeValue(app, 'rois')))
                setRuntimeValuePreserveParse(app, 'rois', 'all');
                applied{end+1} = 'ROIs=all'; %#ok<AGROW>
            end
            try
                if isfield(info, 'fovCount') && info.fovCount > 0
                    app.RuntimeFieldHandles.fovs.Tooltip = sprintf('Detected %d FOV(s): %s', info.fovCount, strjoin(info.fovNames, ', '));
                end
                if isfield(info, 'maxFrame') && ~isempty(info.maxFrame) && info.maxFrame > 0
                    app.RuntimeFieldHandles.frames.Tooltip = sprintf('Detected frames: 1:%d', info.maxFrame);
                end
                if isfield(info, 'roiCount') && info.roiCount > 0
                    app.RuntimeFieldHandles.rois.Tooltip = sprintf('Detected %d existing ROI(s); default runtime selection is all.', info.roiCount);
                end
                if isfield(info, 'rawDataPath') && ~isempty(info.rawDataPath)
                    app.RuntimeFieldHandles.rawDataPath.Tooltip = ['Project raw data link: ' info.rawDataPath];
                else
                    app.RuntimeFieldHandles.rawDataPath.Tooltip = 'No raw data link found in the selected project.';
                end
            catch
            end
            if ~isempty(applied)
                updateRuntimeResourceInventory(app);
                refreshModuleTabs(app);
                redrawGraph(app);
            end
        end

        function syncRuntimeValueToNodeParams(app, key)
            if ~strcmp(char(string(key)), 'rawDataPath')
                return;
            end
            rawDataPath = getRuntimeValue(app, 'rawDataPath');
            changed = false;
            for i = 1:numel(app.Data.nodes)
                if ~strcmpi(char(string(getField(app, app.Data.nodes(i), 'type', ''))), 'dataLoader')
                    continue;
                end
                if ~isfield(app.Data.nodes(i), 'params') || ~isstruct(app.Data.nodes(i).params)
                    app.Data.nodes(i).params = struct();
                end
                current = '';
                if isfield(app.Data.nodes(i).params, 'path')
                    current = char(string(app.Data.nodes(i).params.path));
                end
                if ~strcmp(current, rawDataPath)
                    app.Data.nodes(i).params.path = rawDataPath;
                    changed = true;
                end
            end
            if changed
                rebuildModuleTabsByType(app, 'dataLoader');
            end
        end

        function prompt = runtimePromptForKey(app, key) %#ok<INUSD>
            switch char(string(key))
                case 'fovs'
                    prompt = ['FOV selection: all or 1-based indices (1,3 or 1:4). ' ...
                        'In project mode, explicit IDs such as Pos11_3 are also accepted.'];
                case 'frames'
                    prompt = 'Frame selection: all, 1:50, or 1,5,9';
                case 'rois'
                    prompt = 'ROI selection: all, selected ROI ids, or leave empty until ROIs exist';
                otherwise
                    prompt = 'Value:';
            end
        end

        function updateRuntimeInputStates(app)
            if isempty(fieldnames(app.RuntimeFieldHandles))
                return;
            end
            refreshProjectDropdown(app);
            keys = fieldnames(app.RuntimeFieldHandles);
            for i = 1:numel(keys)
                key = keys{i};
                field = app.RuntimeFieldHandles.(key);
                if ~isvalid(field)
                    continue;
                end
                field.FontColor = [0 0 0];
                field.BackgroundColor = [1 1 1];
                field.Enable = 'on';
            end

            if ~app.RuntimeModeUnlocked
                setRuntimeControlTreeEnabled(app, app.RunTab, false);
                setRuntimeArtifactButtonsEnabled(app, false);
                try, app.RunButton.Enable = 'off'; catch, end
                try, app.SmokeTestButton.Enable = 'off'; catch, end
                return;
            end
            setRuntimeArtifactButtonsEnabled(app, true);
            try, app.RunButton.Enable = ternary(app, ~app.RunLaunchInProgress && isempty(app.ActiveRunMode), 'on', 'off'); catch, end
            try, app.SmokeTestButton.Enable = 'on'; catch, end

            projectPath = strtrim(getRuntimeValue(app, 'projectPath'));
            rawDataPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
            rawOk = ~isempty(rawDataPath) && (app.OpeningPipeline || exist(rawDataPath, 'dir') == 7);
            startsFromProject = runtimeStartsFromExistingProject(app);
            startsFromClassifier = runtimeStartsFromClassifier(app);
            allowProjectCreation = ~startsFromProject && ~startsFromClassifier;
            if app.OpeningPipeline
                projectPathOk = ~isempty(projectPath);
            else
                projectPathOk = runtimeProjectPathUsable(app, projectPath, allowProjectCreation);
            end
            loadedProjectOk = startsFromProject && hasLoadedRuntimeProject(app);
            projectOk = projectPathOk || loadedProjectOk;

            try
                if startsFromClassifier
                    try, app.RuntimeProjectTargetLabel.Text = 'Source classifier'; catch, end
                    app.RuntimeFieldHandles.projectPath.Tooltip = 'Informational: classifier whose attached ROIs are the runtime dataset. It is independent from classifier models linked to pipeline nodes.';
                    app.RuntimeFieldHandles.rawDataPath.Tooltip = 'Classifier mode: raw data folder is not used. ROI image data already attached to the classifier are classified.';
                    app.RuntimeFieldHandles.projectSource.Enable = ternary(app, ~app.RuntimeInputModeLocked, 'on', 'off');
                    app.RuntimeButtonHandles.projectPath.Text = 'Refresh classifiers';
                    app.RuntimeButtonHandles.projectPath.Tooltip = 'Refresh classifiers currently loaded as independent objects or inside workspace projects.';
                    if ~app.RuntimeInputModeLocked
                        try, app.RuntimeFieldHandles.projectPath.Visible = 'off'; catch, end
                    end
                elseif startsFromProject
                    try, app.RuntimeProjectTargetLabel.Text = 'Project'; catch, end
                    try, app.RuntimeFieldHandles.projectPath.Visible = 'on'; catch, end
                    app.RuntimeFieldHandles.projectPath.Tooltip = 'Read mode: this project supplies existing FOVs, ROIs, channels and dataseries. Raw-image nodes also require usable FOV image sources saved in the project.';
                    app.RuntimeFieldHandles.rawDataPath.Tooltip = 'Informational only in project-input mode: raw source path inferred from saved project FOVs, when available.';
                    app.RuntimeFieldHandles.projectSource.Enable = 'on';
                    app.RuntimeButtonHandles.projectPath.Text = 'Browse existing...';
                    app.RuntimeButtonHandles.projectPath.Tooltip = 'Load an existing DetecDiv project JSON or legacy MAT file.';
                else
                    try, app.RuntimeProjectTargetLabel.Text = 'Project'; catch, end
                    try, app.RuntimeFieldHandles.projectPath.Visible = 'on'; catch, end
                    app.RuntimeFieldHandles.projectPath.Tooltip = 'Write target: dataloader, ROI modules and downstream outputs are written into this project.';
                    app.RuntimeFieldHandles.rawDataPath.Tooltip = 'Read source: raw parser supplies FOV/frame/channel inventory for this run.';
                    app.RuntimeFieldHandles.projectSource.Enable = 'off';
                    app.RuntimeButtonHandles.projectPath.Text = 'Set project path...';
                    app.RuntimeButtonHandles.projectPath.Tooltip = 'Create a new shallow project from scratch and use it as the raw-run target.';
                end
            catch
            end

            try
                if app.RuntimeInputModeLocked && isfield(app.RuntimeFieldHandles, 'inputSourceMode') && ...
                        isvalid(app.RuntimeFieldHandles.inputSourceMode)
                    app.RuntimeFieldHandles.inputSourceMode.Enable = 'off';
                    if isempty(strtrim(app.RuntimeInputModeLockReason))
                        app.RuntimeFieldHandles.inputSourceMode.Tooltip = 'Input mode was fixed by the app launch context.';
                    else
                        app.RuntimeFieldHandles.inputSourceMode.Tooltip = app.RuntimeInputModeLockReason;
                    end
                end
            catch
            end

            if startsFromClassifier
                markRuntimeField(app, 'projectPath', 'blocked', 'The selected source classifier supplies ROIs only; pipeline-node classifier links still supply inference models.');
                markRuntimeField(app, 'rawDataPath', 'blocked', 'Classifier mode uses classifier.roi image data, not a raw data folder.');
                setRuntimeButtonEnabled(app, 'projectPath', ~app.RuntimeInputModeLocked);
                setRuntimeButtonEnabled(app, 'rawDataPath', false);
                if ~app.RuntimeInputModeLocked && isempty(app.RuntimeClassifierSource)
                    markRuntimeField(app, 'projectSource', 'missing', 'Select the classifier whose attached ROIs will be processed. This does not select a model for pipeline nodes.');
                end
                if app.RuntimeInputModeLocked
                    classifierIntent = lower(strtrim(getRuntimeValue(app, 'intent')));
                    lockedMsg = 'Fixed by classifierGUI train/test selection for this classifier run.';
                    lockRuntimeFieldForClassifier(app, 'fovs', lockedMsg);
                    if strcmp(classifierIntent, 'validate')
                        unlockRuntimeFieldForClassifier(app, 'frames', ...
                            'Validation run: optionally restrict the evaluated frame range. Leave empty for all frames.');
                    else
                        lockRuntimeFieldForClassifier(app, 'frames', ...
                            'Training run: frames are defined by the exported training set/framebank.');
                    end
                    lockRuntimeFieldForClassifier(app, 'rois', lockedMsg);
                    lockRuntimeFieldForClassifier(app, 'channels', lockedMsg);
                    lockRuntimeFieldForClassifier(app, 'outputPolicy', lockedMsg);
                    setRuntimeButtonEnabled(app, 'channels', false);
                end
            elseif ~isempty(projectPath) && ~projectPathOk && ~loadedProjectOk
                if startsFromProject
                    markRuntimeField(app, 'projectPath', 'missing', 'Project must be an existing folder or project JSON/legacy MAT file.');
                else
                    markRuntimeField(app, 'projectPath', 'missing', 'Project target parent folder must exist or be creatable.');
                end
            end

            if startsFromClassifier
                % Project/raw warnings are intentionally suppressed in classifier mode.
            elseif startsFromProject
                sourcePath = projectSourcePath(app, app.CurrentProject);
                if ~isempty(sourcePath)
                    tip = ['Read-only: raw source path recorded in the selected project/FOVs: ' sourcePath];
                else
                    tip = 'Read-only: no raw source path could be inferred from the selected project.';
                end
                try
                    app.RuntimeFieldHandles.rawDataPath.Tooltip = tip;
                catch
                end
                try
                    app.RuntimeButtonHandles.rawDataPath.Text = 'Relink...';
                    app.RuntimeButtonHandles.rawDataPath.Tooltip = 'Rebase saved project FOV raw-data paths without changing the pipeline input mode.';
                catch
                end
                markRuntimeField(app, 'rawDataPath', 'blocked', tip);
                setRuntimeButtonEnabled(app, 'rawDataPath', true);
            elseif ~selectedRunHasNodeType(app, 'dataLoader')
                markRuntimeField(app, 'rawDataPath', 'warning', ...
                    'Select a dataloader node in the run module list. The raw-data path can be set now.');
                try
                    app.RuntimeButtonHandles.rawDataPath.Text = 'Browse...';
                    app.RuntimeButtonHandles.rawDataPath.Tooltip = 'Select a raw image/data folder. Include a dataloader node in the run to parse it.';
                catch
                end
                setRuntimeButtonEnabled(app, 'rawDataPath', true);
            elseif pipelineHasNodeType(app, 'dataLoader') && ~rawOk
                markRuntimeField(app, 'rawDataPath', 'missing', 'Required when a dataloader run has no existing project input.');
                try
                    app.RuntimeButtonHandles.rawDataPath.Text = 'Browse...';
                    app.RuntimeButtonHandles.rawDataPath.Tooltip = 'Select a raw image/data folder parsed by the dataloader.';
                catch
                end
                setRuntimeButtonEnabled(app, 'rawDataPath', true);
            else
                try
                    app.RuntimeButtonHandles.rawDataPath.Text = 'Browse...';
                    app.RuntimeButtonHandles.rawDataPath.Tooltip = 'Select a raw image/data folder parsed by the dataloader.';
                catch
                end
                setRuntimeButtonEnabled(app, 'rawDataPath', true);
            end

            if startsFromClassifier
                projectOk = true;
            elseif ~startsFromProject && ~projectOk
                markRuntimeField(app, 'projectPath', 'missing', 'Raw-data mode requires creating a new target project.');
            end

            if startsFromClassifier && app.RuntimeInputModeLocked
                % Already locked above; only the execution target remains user-editable.
            elseif startsFromClassifier
                setRuntimeButtonEnabled(app, 'channels', true);
            elseif startsFromProject && ~projectOk
                markRuntimeField(app, 'channels', 'blocked', 'Select an existing project before selecting ROI channels.');
                setRuntimeButtonEnabled(app, 'channels', false);
            elseif ~startsFromProject && ~projectOk && ~rawOk
                markRuntimeField(app, 'channels', 'blocked', 'Select an existing project or raw data folder before selecting channels.');
                setRuntimeButtonEnabled(app, 'channels', false);
            else
                setRuntimeButtonEnabled(app, 'channels', true);
            end

            [severity, message] = outputPolicyCompatibility(app);
            if ~strcmp(severity, 'ok')
                markRuntimeField(app, 'outputPolicy', severity, message);
            end
            applyBatchPrototypeUiRestrictions(app);
        end

        function lockRuntimeFieldForClassifier(app, key, tooltip)
            try
                markRuntimeField(app, key, 'blocked', tooltip);
                if isfield(app.RuntimeFieldHandles, key) && isvalid(app.RuntimeFieldHandles.(key))
                    app.RuntimeFieldHandles.(key).Enable = 'off';
                end
            catch
            end
        end

        function unlockRuntimeFieldForClassifier(app, key, tooltip)
            try
                if isfield(app.RuntimeFieldHandles, key) && isvalid(app.RuntimeFieldHandles.(key))
                    field = app.RuntimeFieldHandles.(key);
                    field.Enable = 'on';
                    field.FontColor = [0 0 0];
                    field.BackgroundColor = [1 1 1];
                    field.Tooltip = tooltip;
                end
            catch
            end
        end

        function markRuntimeField(app, key, state, tooltip)
            if ~isfield(app.RuntimeFieldHandles, key)
                return;
            end
            field = app.RuntimeFieldHandles.(key);
            if ~isvalid(field)
                return;
            end
            switch char(string(state))
                case 'missing'
                    field.FontColor = [0.70 0.05 0.05];
                    field.BackgroundColor = [1.00 0.92 0.92];
                    field.Enable = 'on';
                case 'blocked'
                    field.FontColor = [0.45 0.45 0.45];
                    field.BackgroundColor = [0.94 0.94 0.94];
                    field.Enable = 'off';
                case 'warning'
                    field.FontColor = [0.45 0.25 0.00];
                    field.BackgroundColor = [1.00 0.96 0.82];
                    field.Enable = 'on';
            end
            try
                field.Tooltip = tooltip;
            catch
            end
        end

        function setRuntimeButtonEnabled(app, key, tf)
            if isfield(app.RuntimeButtonHandles, key) && isvalid(app.RuntimeButtonHandles.(key))
                app.RuntimeButtonHandles.(key).Enable = ternary(app, tf, 'on', 'off');
            end
        end

        function tf = pipelineHasNodeType(app, nodeType)
            tf = false;
            for i = 1:numel(app.Data.nodes)
                if strcmpi(char(string(getField(app, app.Data.nodes(i), 'type', ''))), nodeType)
                    tf = true;
                    return;
                end
            end
        end

        function setRuntimeModeUnlocked(app, tf)
            app.RuntimeModeUnlocked = logical(tf);
            app.Data.runMode = logical(tf);
            try, app.RunTab.Enable = ternary(app, tf, 'on', 'off'); catch, end
            setRuntimeControlTreeEnabled(app, app.RunTab, tf);
            if app.HubRunUiLocked
                applyHubRunUiLock(app, true);
            end
            updateRuntimeInputStates(app);
            if app.BatchPrototypeMode
                setRuntimeStatus(app, sprintf('Batch prototype mode.\nSet runtime parameters and target, then click Use Prototype.'));
            elseif tf
                setRuntimeStatus(app, sprintf('Runtime mode enabled.\nEdit run inputs, then launch Run.'));
            else
                setRuntimeStatus(app, sprintf('Template mode: runtime locked.\nClick New Run to configure execution.'));
            end
        end

        function applyBatchPrototypeUiRestrictions(app)
            if ~app.BatchPrototypeMode
                return;
            end
            disabledControls = {'RunButton','SmokeTestButton', ...
                'OpenRunFolderButton','RunLogButton','RunParamsButton','ReviewRunButton', ...
                'ForkgraphButton','MergegraphButton','InsertbeforeselectedButton','DeleteselectedButton'};
            for i = 1:numel(disabledControls)
                try
                    h = app.(disabledControls{i});
                    if ~isempty(h) && isvalid(h)
                        h.Enable = 'off';
                    end
                catch
                end
            end
            disabledMenus = {'NewpipelineMenu','LoadpipelineMenu','SavecurrentpipelineMenu', ...
                'SavepipelineasMenu','LoadrunMenu','SaverunMenu','SaverunasMenu','ModulesMenu'};
            for i = 1:numel(disabledMenus)
                try
                    h = app.(disabledMenus{i});
                    if ~isempty(h) && isvalid(h)
                        h.Enable = 'off';
                    end
                catch
                end
            end
            try, app.RunButton.Text = 'Run disabled'; catch, end
            try, app.SmokeTestButton.Text = 'Smoke disabled'; catch, end
            try, app.NewRunButton.Text = 'Use Prototype'; catch, end
            try, app.NewRunButton.Enable = 'on'; catch, end
            try, app.UISelectedModuleTable.ColumnEditable = [false false false false]; catch, end
            try, app.IdEditField.Enable = 'off'; catch, end
            try, app.TypeDropDown.Enable = 'off'; catch, end
            try, app.SubtypeDropDown.Enable = 'off'; catch, end
        end

        function setRuntimeControlTreeEnabled(app, parent, tf) %#ok<INUSD>
            try
                kids = parent.Children;
                for i = 1:numel(kids)
                    try
                        if isprop(kids(i), 'Enable')
                            kids(i).Enable = ternary(app, tf, 'on', 'off');
                        end
                    catch
                    end
                    try
                        setRuntimeControlTreeEnabled(app, kids(i), tf);
                    catch
                    end
                end
            catch
            end
        end

        function setRuntimeArtifactButtonsEnabled(app, tf)
            names = {'OpenRunFolderButton','RunLogButton','RunParamsButton','ReviewRunButton'};
            for i = 1:numel(names)
                try
                    h = app.(names{i});
                    if ~isempty(h) && isvalid(h)
                        h.Enable = ternary(app, tf, 'on', 'off');
                    end
                catch
                end
            end
        end

        function applyHubRunUiLock(app, tf)
            app.HubRunUiLocked = logical(tf);
            if ~app.RuntimeModeUnlocked
                return;
            end
            try, setRuntimeControlTreeEnabled(app, app.RunTab, ~tf); catch, end
            try, app.UISelectedModuleTable.ColumnEditable = ternary(app, ~tf, [true false false false], [false false false false]); catch, end
            names = {'NewRunButton','SmokeTestButton','CheckbuildButton'};
            for i = 1:numel(names)
                try
                    h = app.(names{i});
                    if ~isempty(h) && isvalid(h)
                        h.Enable = ternary(app, ~tf, 'on', 'off');
                    end
                catch
                end
            end
            try
                app.RunButton.Enable = ternary(app, ~tf && ~app.RunLaunchInProgress && isempty(app.ActiveRunMode), 'on', 'off');
            catch
            end
            try, app.CancelrunButton.Enable = ternary(app, tf, 'on', 'off'); catch, end
            setRuntimeArtifactButtonsEnabled(app, true);
        end

        function setRuntimeStatus(app, textValue)
            try
                updatePipelineRunStatusBar(app, char(string(textValue)));
            catch
            end
        end

        function tf = selectedRunHasNodeType(app, nodeType)
            tf = false;
            ids = selectedRunNodeIds(app);
            for i = 1:numel(ids)
                idx = find(strcmp({app.Data.nodes.id}, ids{i}), 1);
                if isempty(idx)
                    continue;
                end
                if strcmpi(char(string(getField(app, app.Data.nodes(idx), 'type', ''))), nodeType)
                    tf = true;
                    return;
                end
            end
        end

        function ids = selectedRunNodeIdsByType(app, nodeTypes)
            ids = {};
            if ischar(nodeTypes) || (isstring(nodeTypes) && isscalar(nodeTypes))
                nodeTypes = {char(string(nodeTypes))};
            else
                nodeTypes = cellstr(string(nodeTypes(:)))';
            end
            nodeTypes = lower(nodeTypes);
            activeIds = selectedRunNodeIds(app);
            for i = 1:numel(activeIds)
                idx = find(strcmp({app.Data.nodes.id}, activeIds{i}), 1);
                if isempty(idx)
                    continue;
                end
                nodeType = lower(char(string(getField(app, app.Data.nodes(idx), 'type', ''))));
                if any(strcmp(nodeTypes, nodeType))
                    ids{end+1} = activeIds{i}; %#ok<AGROW>
                end
            end
        end

        function issues = attachedRoiModuleChannelIssues(app)
            issues = {};
            if ~runtimeStartsFromClassifier(app) || isempty(app.ExplicitRuntimeRoiList)
                return;
            end
            moduleIds = selectedRunNodeIdsByType(app, {'classifier','processor'});
            if isempty(moduleIds)
                return;
            end
            [roiList, roiIndices] = selectedClassifierRoisForValidation(app);
            if isempty(roiList)
                return;
            end
            for ci = 1:numel(moduleIds)
                nodeIdx = find(strcmp({app.Data.nodes.id}, moduleIds{ci}), 1);
                if isempty(nodeIdx)
                    continue;
                end
                node = app.Data.nodes(nodeIdx);
                classiObj = [];
                nodeType = lower(char(string(getField(app, node, 'type', ''))));
                if strcmp(nodeType, 'classifier') && ...
                        classifierUsesFramebankTrainingInput(app, node)
                    % CellposeSAM training consumes the already formatted
                    % framebank. Its ROI IDs are provenance only; the
                    % source ROI channel labels are not training inputs.
                    continue;
                end
                if strcmp(nodeType, 'classifier')
                    try
                        classiObj = linkedClassifierObject(app, node);
                    catch ME
                        issues{end+1} = sprintf('Cannot inspect classifier ROI channels for node %s: %s', ...
                            char(string(moduleIds{ci})), ME.message); %#ok<AGROW>
                        continue;
                    end
                end
                requiredChannels = moduleStoredInputChannels(app, node, classiObj);
                if isempty(requiredChannels)
                    continue;
                end
                presentIdx = [];
                missingIdx = [];
                missingFirstChannels = {};
                for ri = 1:numel(roiList)
                    roiObj = roiList(ri);
                    if roiHasRequiredChannels(app, roiObj, requiredChannels)
                        presentIdx(end+1) = roiIndices(ri); %#ok<AGROW>
                    else
                        missingIdx(end+1) = roiIndices(ri); %#ok<AGROW>
                        firstChannel = roiFirstDisplayChannel(app, roiObj);
                        if ~isempty(firstChannel)
                            missingFirstChannels{end+1} = firstChannel; %#ok<AGROW>
                        end
                    end
                end
                if isempty(missingIdx)
                    continue;
                end
                nodeName = char(string(getField(app, node, 'name', moduleIds{ci})));
                issues{end+1} = formatRoiModuleChannelIssue(app, nodeName, nodeType, requiredChannels, ...
                    presentIdx, missingIdx, missingFirstChannels); %#ok<AGROW>
            end
        end

        function tf = classifierUsesFramebankTrainingInput(app, node)
            tf = false;
            pkg = strtrim(char(string(getField(app, node, 'pkg', ''))));
            if isempty(pkg)
                func = strtrim(char(string(getField(app, node, 'func', ''))));
                if contains(func, '.')
                    pkg = char(extractBefore(string(func), '.'));
                end
            end
            if ~strcmpi(pkg, 'cellposesam')
                return;
            end

            params = getField(app, node, 'params', struct());
            intent = getRuntimeValue(app, 'intent');
            if isstruct(params)
                % Match runPipeline.classifierRunIntent precedence exactly:
                % node operation, node intent, then the runtime intent.
                if isfield(params, 'operation') && ~isempty(params.operation)
                    intent = params.operation;
                elseif isfield(params, 'intent') && ~isempty(params.intent)
                    intent = params.intent;
                end
            end
            try
                intent = normalizeStartupIntent(app, intent);
            catch
                intent = lower(strtrim(char(string(intent))));
            end
            tf = strcmpi(char(string(intent)), 'train');
        end

        function [roiList, roiIndices] = selectedClassifierRoisForValidation(app)
            roiList = [];
            roiIndices = [];
            allRois = app.ExplicitRuntimeRoiList;
            if isempty(allRois)
                return;
            end
            roiSel = parseLooseSelection(app, getRuntimeValue(app, 'rois'));
            if isempty(roiSel)
                roiIndices = 1:numel(allRois);
            elseif isnumeric(roiSel)
                roiIndices = round(double(roiSel(:)'));
                roiIndices = roiIndices(isfinite(roiIndices) & roiIndices >= 1 & roiIndices <= numel(allRois));
            else
                wanted = cellstr(string(roiSel(:)));
                labels = cell(1, numel(allRois));
                for i = 1:numel(allRois)
                    labels{i} = safeRoiLabel(app, allRois(i), i);
                end
                for i = 1:numel(wanted)
                    match = find(strcmp(labels, wanted{i}), 1);
                    if ~isempty(match)
                        roiIndices(end+1) = match; %#ok<AGROW>
                    end
                end
            end
            roiIndices = unique(roiIndices, 'stable');
            if isempty(roiIndices)
                return;
            end
            roiList = allRois(roiIndices);
        end

        function channels = moduleStoredInputChannels(app, node, classiObj)
            channels = {};
            p = getField(app, node, 'params', struct());
            try
                contract = pipelineNodeContract(node);
                resources = getField(app, contract, 'resources', struct());
                inputs = getField(app, resources, 'in', struct([]));
                for i = 1:numel(inputs)
                    if ~strcmpi(char(string(getField(app, inputs(i), 'type', ''))), 'channel')
                        continue;
                    end
                    param = char(string(getField(app, inputs(i), 'param', '')));
                    if isempty(param)
                        param = char(string(getField(app, inputs(i), 'nameParam', '')));
                    end
                    if ~isempty(param) && isstruct(p) && isfield(p, param) && ~isempty(p.(param))
                        values = normalizeChannelCellText(app, p.(param));
                        values = values(~cellfun(@(value) ...
                            channelBindingComesFromActiveNode(app, node, inputs(i), value), values));
                        channels = [channels values]; %#ok<AGROW>
                    end
                end
            catch
            end
            if isstruct(p)
                if isempty(channels) && isfield(p, 'channel') && ~isempty(p.channel)
                    channels = normalizeChannelCellText(app, p.channel);
                elseif isempty(channels) && isfield(p, 'channels') && ~isempty(p.channels)
                    channels = normalizeChannelCellText(app, p.channels);
                elseif isempty(channels) && isfield(p, 'channelName') && ~isempty(p.channelName)
                    channels = normalizeChannelCellText(app, p.channelName);
                end
            end
            if isempty(channels) && ~isempty(classiObj) && isa(classiObj, 'classi')
                try
                    if isprop(classiObj, 'channelName') && ~isempty(classiObj.channelName)
                        channels = normalizeChannelCellText(app, classiObj.channelName);
                    end
                catch
                end
            end
            if isempty(channels)
                return;
            end
            skip = strcmpi(string(channels), 'all') | strcmpi(string(channels), 'auto') | ...
                startsWith(lower(string(channels)), '<') | startsWith(string(channels), '@');
            channels = channels(~skip);
            channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
        end

        function tf = channelBindingComesFromActiveNode(app, node, spec, value)
            tf = false;
            value = strtrim(char(string(value)));
            sourceNode = symbolicBindingSourceNode(app, value);
            if ~isempty(sourceNode)
                tf = isRunNodeActive(app, sourceNode);
                return;
            end
            try
                inputReport = currentResourceInputReport(app, node, spec);
                if ~isstruct(inputReport) || ~isfield(inputReport, 'available')
                    return;
                end
                available = inputReport.available;
                for i = 1:numel(available)
                    concrete = char(string(getField(app, available(i), 'concreteName', '')));
                    producer = char(string(getField(app, available(i), 'sourceNode', '')));
                    sourceKind = lower(char(string(getField(app, available(i), 'sourceKind', ''))));
                    if strcmpi(concrete, value) && ~isempty(producer) && ...
                            ~any(strcmp(sourceKind, {'context','ctx','runtime'})) && ...
                            isRunNodeActive(app, producer)
                        tf = true;
                        return;
                    end
                end
            catch
                tf = false;
            end
        end

        function channels = normalizeChannelCellText(app, value) %#ok<INUSD>
            channels = {};
            if isempty(value)
                return;
            end
            try
                if iscell(value)
                    channels = cellstr(string(value(:)'));
                else
                    channels = cellstr(string(value(:)'));
                end
                channels = cellfun(@(x) strtrim(char(string(x))), channels, 'UniformOutput', false);
                channels = channels(~cellfun(@isempty, channels));
            catch
                channels = {};
            end
        end

        function tf = roiHasRequiredChannels(app, roiObj, channels) %#ok<INUSD>
            tf = false;
            if isempty(channels)
                return;
            end
            missing = {};
            try
                for i = 1:numel(channels)
                    pix = roiObj.findChannelID(channels{i});
                    if iscell(pix)
                        if isempty(pix) || isempty(pix{1})
                            missing{end+1} = channels{i}; %#ok<AGROW>
                        end
                    elseif isempty(pix)
                        missing{end+1} = channels{i}; %#ok<AGROW>
                    end
                end
            catch
                missing = channels;
            end
            if isempty(missing)
                tf = true;
                return;
            end

            % A classifier snapshot may carry stale, partially loaded ROI
            % display metadata even though newer result channels already
            % exist in the ROI HDF5 file.  Inspect only the HDF5 directory
            % and attributes here; h5info never materializes image arrays.
            diskChannels = roiStoredChannelNames(app, roiObj);
            if isempty(diskChannels)
                return;
            end
            tf = all(ismember(lower(string(missing)), ...
                lower(string(diskChannels))));
        end

        function channels = roiStoredChannelNames(app, roiObj) %#ok<INUSD>
            channels = {};
            if app.RuntimeInventoryMetadataOnly
                return;
            end
            h5File = '';
            try
                h5File = fullfile(char(string(roiObj.path)), ...
                    ['im_' char(string(roiObj.id)) '.h5']);
            catch
                return;
            end
            if isempty(app.RuntimeStoredChannelsCache)
                app.RuntimeStoredChannelsCache = containers.Map('KeyType', 'char', 'ValueType', 'any');
            end
            if isKey(app.RuntimeStoredChannelsCache, h5File)
                channels = app.RuntimeStoredChannelsCache(h5File);
                return;
            end
            if exist(h5File, 'file') ~= 2
                app.RuntimeStoredChannelsCache(h5File) = {};
                return;
            end

            % Runtime-input validation may be refreshed repeatedly while
            % editing a run. Cache the metadata by path and file stamp so a
            % local or network HDF5 is polled only after it changes.
            persistent channelCache
            if isempty(channelCache)
                channelCache = containers.Map('KeyType', 'char', ...
                    'ValueType', 'any');
            end
            fileInfo = dir(h5File);
            signature = sprintf('%.15g:%d', fileInfo.datenum, fileInfo.bytes);
            cacheKey = lower(strrep(h5File, '/', '\'));
            if isKey(channelCache, cacheKey)
                cached = channelCache(cacheKey);
                if isstruct(cached) && isfield(cached, 'signature') && ...
                        strcmp(cached.signature, signature)
                    channels = cached.channels;
                    app.RuntimeStoredChannelsCache(h5File) = channels;
                    return;
                end
            end

            try
                info = h5info(h5File);
                for i = 1:numel(info.Datasets)
                    dataset = info.Datasets(i);
                    channels{end+1} = char(string(dataset.Name)); %#ok<AGROW>
                    try
                        attributeNames = string({dataset.Attributes.Name});
                        attributeIndex = find(strcmpi(attributeNames, ...
                            'channel_name'), 1);
                        if ~isempty(attributeIndex)
                            logicalName = strtrim(char(string( ...
                                dataset.Attributes(attributeIndex).Value)));
                            if ~isempty(logicalName)
                                channels{end+1} = logicalName; %#ok<AGROW>
                            end
                        end
                    catch
                    end
                end
                channels = unique(channels(~cellfun(@isempty, channels)), ...
                    'stable');
                channelCache(cacheKey) = struct( ...
                    'signature', signature, 'channels', {channels});
                app.RuntimeStoredChannelsCache(h5File) = channels;
            catch
                channels = {};
            end
        end

        function name = roiFirstDisplayChannel(app, roiObj) %#ok<INUSD>
            name = '';
            try
                if isprop(roiObj, 'display') && isstruct(roiObj.display) && ...
                        isfield(roiObj.display, 'channel') && ~isempty(roiObj.display.channel)
                    vals = cellstr(string(roiObj.display.channel(:)'));
                    vals = vals(~cellfun(@isempty, vals));
                    if ~isempty(vals)
                        name = vals{1};
                    end
                end
            catch
                name = '';
            end
        end

        function message = formatRoiModuleChannelIssue(app, nodeName, nodeType, requiredChannels, presentIdx, missingIdx, missingFirstChannels)
            lines = {};
            lines{end+1} = sprintf('Channel mismatch in selected classifier ROIs for %s node %s.', ...
                char(string(nodeType)), char(string(nodeName)));
            lines{end+1} = ['Module expects stored input channel(s): ' strjoin(requiredChannels, ', ')];
            lines{end+1} = sprintf('%d ROI(s) contain the required channel(s): %s', ...
                numel(presentIdx), compactNumericSelectionText(app, presentIdx));
            lines{end+1} = sprintf('%d ROI(s) are missing at least one required channel: %s', ...
                numel(missingIdx), compactNumericSelectionText(app, missingIdx));
            summary = summarizeChannelNamesWithCounts(app, missingFirstChannels, 4);
            if ~isempty(summary)
                lines{end+1} = ['First loaded channel in affected ROI(s): ' summary];
            end
            lines{end+1} = 'Fix channel names or select compatible ROIs before launching on Hub.';
            message = strjoin(lines, newline);
        end

        function txt = summarizeChannelNamesWithCounts(app, names, maxNames) %#ok<INUSD>
            txt = '';
            if nargin < 3 || isempty(maxNames)
                maxNames = 4;
            end
            if isempty(names)
                return;
            end
            names = cellstr(string(names(:)'));
            names = names(~cellfun(@isempty, names));
            if isempty(names)
                return;
            end
            u = unique(names, 'stable');
            parts = {};
            nShow = min(numel(u), maxNames);
            for i = 1:nShow
                parts{end+1} = sprintf('%s (%d)', u{i}, sum(strcmp(names, u{i}))); %#ok<AGROW>
            end
            if numel(u) > nShow
                parts{end+1} = sprintf('... (+%d)', numel(u) - nShow); %#ok<AGROW>
            end
            txt = strjoin(parts, ', ');
        end

        function tf = dryRunReportHasBlockingIssues(app, report, ctx)
            tf = false;
            if ~isstruct(report)
                return;
            end
            if nargin >= 3 && dryRunReportOnlyHasClassifierRoiDeferral(app, report, ctx)
                return;
            end
            try
                if isfield(report, 'okStrict') && ~isempty(report.okStrict) && ~logical(report.okStrict)
                    tf = true;
                    return;
                end
            catch
            end
            if isfield(report, 'errors') && ~isempty(report.errors)
                tf = true;
                return;
            end
            try
                if isfield(report, 'solver') && isstruct(report.solver) && ...
                        isfield(report.solver, 'hasBlocking') && logical(report.solver.hasBlocking)
                    tf = true;
                    return;
                end
            catch
            end
            try
                if isfield(report, 'solver') && isstruct(report.solver) && ...
                        isfield(report.solver, 'issues') && ~isempty(report.solver.issues)
                    severities = lower(string({report.solver.issues.severity}));
                    if any(severities == "error")
                        tf = true;
                    end
                end
            catch
            end
        end

        function tf = dryRunReportOnlyHasClassifierRoiDeferral(app, report, ctx)
            tf = false;
            if ~isstruct(report) || ~isstruct(ctx)
                return;
            end
            if ~dryRunContextIsHubClassifierRois(app, ctx)
                return;
            end
            messages = dryRunBlockingMessages(app, report);
            if isempty(messages)
                return;
            end
            for i = 1:numel(messages)
                if ~isClassifierRoiDeferralMessage(app, messages{i})
                    return;
                end
            end
            tf = true;
        end

        function tf = dryRunContextIsHubClassifierRois(app, ctx)
            tf = false;
            try
                target = char(string(getNestedFieldLocal(app, ctx, {'run','executionTarget'}, runtimeExecutionTarget(app))));
                if ~strcmpi(target, 'hub')
                    return;
                end
                mode = char(string(getNestedFieldLocal(app, ctx, {'run','inputSourceMode'}, getRuntimeValue(app, 'inputSourceMode'))));
                source = char(string(getNestedFieldLocal(app, ctx, {'run','inputSource'}, '')));
                isClassifierInput = strcmpi(mode, 'classifier_rois') || contains(lower(source), 'classifier');
                if ~isClassifierInput
                    return;
                end
                if isempty(app.ExplicitRuntimeRoiList)
                    return;
                end
                tf = selectedRunHasNodeType(app, 'classifier');
            catch
                tf = false;
            end
        end

        function messages = dryRunBlockingMessages(app, report)
            messages = {};
            if ~isstruct(report)
                return;
            end
            if isfield(report, 'errors') && ~isempty(report.errors)
                messages = [messages cellstr(string(report.errors))]; %#ok<AGROW>
            end
            try
                if isfield(report, 'solver') && isstruct(report.solver) && ...
                        isfield(report.solver, 'issues') && ~isempty(report.solver.issues)
                    for i = 1:numel(report.solver.issues)
                        issue = report.solver.issues(i);
                        severity = lower(char(string(getField(app, issue, 'severity', ''))));
                        if strcmp(severity, 'info')
                            continue;
                        end
                        msg = strtrim(char(string(getField(app, issue, 'message', ''))));
                        if ~isempty(msg)
                            messages{end+1} = msg; %#ok<AGROW>
                        end
                    end
                end
            catch
            end
            messages = unique(messages(~cellfun(@isempty, messages)), 'stable');
        end

        function tf = isClassifierRoiDeferralMessage(app, message) %#ok<INUSD>
            msg = lower(strtrim(char(string(message))));
            tf = contains(msg, 'missing inputs for node') && contains(msg, 'roilist');
            tf = tf || contains(msg, 'requires roi content') && ...
                contains(msg, 'not available in the current graph state');
        end

        function report = annotateDryRunClassifierRoiDeferral(app, report)
            if ~isstruct(report)
                return;
            end
            originalErrors = {};
            originalOkStrict = [];
            originalSolver = struct();
            try
                if isfield(report, 'errors')
                    originalErrors = report.errors;
                    report.errors = {};
                end
            catch
            end
            try
                if isfield(report, 'okStrict')
                    originalOkStrict = report.okStrict;
                    report.okStrict = true;
                end
            catch
            end
            try
                if isfield(report, 'ok')
                    report.ok = true;
                end
            catch
            end
            try
                if isfield(report, 'solver') && isstruct(report.solver)
                    originalSolver = report.solver;
                    if isfield(report.solver, 'hasBlocking')
                        report.solver.hasBlocking = false;
                    end
                    if isfield(report.solver, 'summary') && isstruct(report.solver.summary)
                        report.solver.summary.errors = 0;
                    end
                end
            catch
            end
            report.classifierRoiDeferred = true;
            report.deferredClassifierRoiBinding = struct( ...
                'reason', 'Classifier-attached ROI handles are resolved on the Hub worker.', ...
                'originalErrors', {originalErrors}, ...
                'originalOkStrict', originalOkStrict, ...
                'originalSolver', originalSolver);
            try
                warningText = 'Classifier ROI binding is deferred to the Hub worker; attached classifier ROIs were checked before launch.';
                if isfield(report, 'warnings') && ~isempty(report.warnings)
                    report.warnings = [cellstr(string(report.warnings)) {warningText}];
                else
                    report.warnings = {warningText};
                end
            catch
            end
        end

        function message = formatDryRunBlockingMessage(app, report)
            lines = {'Dry-run validation found blocking issue(s). The run was not submitted to Hub.'};
            if isstruct(report)
                if isfield(report, 'errors') && ~isempty(report.errors)
                    errs = cellstr(string(report.errors));
                    lines{end+1} = ''; %#ok<AGROW>
                    lines{end+1} = 'Errors:'; %#ok<AGROW>
                    for i = 1:min(numel(errs), 8)
                        lines{end+1} = ['- ' errs{i}]; %#ok<AGROW>
                    end
                end
                try
                    if isfield(report, 'solver') && isstruct(report.solver) && ...
                            isfield(report.solver, 'issues') && ~isempty(report.solver.issues)
                        solverLines = {};
                        for i = 1:numel(report.solver.issues)
                            issue = report.solver.issues(i);
                            severity = lower(char(string(getField(app, issue, 'severity', ''))));
                            if strcmp(severity, 'info')
                                continue;
                            end
                            msg = char(string(getField(app, issue, 'message', '')));
                            if ~isempty(msg)
                                solverLines{end+1} = ['- ' msg]; %#ok<AGROW>
                            end
                            if numel(solverLines) >= 8
                                break;
                            end
                        end
                        if ~isempty(solverLines)
                            lines{end+1} = ''; %#ok<AGROW>
                            lines{end+1} = 'Solver issues:'; %#ok<AGROW>
                            lines = [lines solverLines]; %#ok<AGROW>
                        end
                    end
                catch
                end
            end
            message = strjoin(lines, newline);
        end

        function tf = selectedRunNeedsChannels(app)
            tf = selectedRunHasNodeType(app, 'roiExtract') || ...
                selectedRunHasNodeType(app, 'classifier') || ...
                selectedRunHasNodeType(app, 'processor');
        end

        function tf = rawParserIsCurrent(app, rawDataPath)
            tf = false;
            if ~isfield(app.RuntimeParseInfo, 'path') || isempty(app.RuntimeParseInfo.path)
                return;
            end
            tf = strcmp(char(string(app.RuntimeParseInfo.path)), char(string(rawDataPath))) && ...
                isfield(app.RuntimeParseInfo, 'ok') && logical(app.RuntimeParseInfo.ok);
        end

        function tf = rawParserHasNoChannels(app)
            tf = isempty(runtimeConcreteChannels(app));
        end

        function setRefreshingTabs(app, value)
            app.IsRefreshingTabs = logical(value);
        end

        function setModuleTabRefreshSuspended(app, value)
            app.ModuleTabRefreshSuspended = logical(value);
        end

        function beginModuleTabRefreshBatch(app)
            app.ModuleTabRefreshBatchDepth = app.ModuleTabRefreshBatchDepth + 1;
        end

        function endModuleTabRefreshBatch(app, flushPending)
            if nargin < 2
                flushPending = true;
            end
            app.ModuleTabRefreshBatchDepth = max(0, app.ModuleTabRefreshBatchDepth - 1);
            if app.ModuleTabRefreshBatchDepth == 0 && logical(flushPending) && ...
                    app.ModuleTabRefreshPending && ~app.ModuleTabRefreshSuspended
                refreshModuleTabs(app);
            end
        end

        function setRedrawingGraph(app, value)
            app.IsRedrawingGraph = logical(value);
        end

        function selectExistingModuleTab(app, node)
            if isempty(app.DynamicModuleTabs)
                return;
            end
            nodeId = char(string(getField(app, node, 'id', '')));
            for i = 1:numel(app.DynamicModuleTabs)
                try
                    t = app.DynamicModuleTabs(i);
                    if isvalid(t) && isstruct(t.UserData) && isfield(t.UserData, 'nodeId') && strcmp(char(string(t.UserData.nodeId)), nodeId)
                        app.IsRefreshingTabs = true;
                        cleanupObj = onCleanup(@()setRefreshingTabs(app, false)); %#ok<NASGU>
                        app.TabGroup.SelectedTab = t;
                        return;
                    end
                catch
                end
            end
        end

        function renameSelectedModuleTab(app, oldId, newId)
            for i = 1:numel(app.DynamicModuleTabs)
                try
                    t = app.DynamicModuleTabs(i);
                    if isvalid(t) && isstruct(t.UserData) && isfield(t.UserData, 'nodeId') && strcmp(char(string(t.UserData.nodeId)), oldId)
                        t.UserData.nodeId = newId;
                        t.Title = truncateTabTitle(app, newId);
                        return;
                    end
                catch
                end
            end
        end

        function rebuildSelectedModuleTab(app)
            if isnan(app.SelectedNodeIndex) || app.SelectedNodeIndex < 1 || app.SelectedNodeIndex > numel(app.Data.nodes)
                return;
            end
            rebuildModuleTabForNode(app, app.SelectedNodeIndex);
        end

        function rebuildModuleTabForNode(app, nodeIdx)
            if nodeIdx < 1 || nodeIdx > numel(app.Data.nodes)
                return;
            end
            if isempty(app.DynamicModuleTabs)
                refreshModuleTabs(app);
                return;
            end
            previousFocus = captureTabFocus(app);
            node = app.Data.nodes(nodeIdx);
            nodeId = char(string(getField(app, node, 'id', '')));
            for i = 1:numel(app.DynamicModuleTabs)
                try
                    t = app.DynamicModuleTabs(i);
                    if isvalid(t) && isstruct(t.UserData) && isfield(t.UserData, 'nodeId') && strcmp(char(string(t.UserData.nodeId)), nodeId)
                        app.IsRefreshingTabs = true;
                        cleanupObj = onCleanup(@()setRefreshingTabs(app, false)); %#ok<NASGU>
                        delete(t.Children);
                        buildModuleTab(app, t, node);
                        activeIds = selectedRunNodeIds(app);
                        configureModuleTabActiveState(app, t, node, activeIds);
                        restoreTabFocus(app, previousFocus);
                        return;
                    end
                catch
                end
            end
            refreshModuleTabs(app);
        end

        function refreshModuleTabActiveStates(app)
            if isempty(app.DynamicModuleTabs)
                return;
            end
            activeIds = selectedRunNodeIds(app);
            for i = 1:numel(app.DynamicModuleTabs)
                try
                    t = app.DynamicModuleTabs(i);
                    if ~isvalid(t) || ~isstruct(t.UserData) || ~isfield(t.UserData, 'nodeId')
                        continue;
                    end
                    nodeId = char(string(t.UserData.nodeId));
                    idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
                    if isempty(idx)
                        continue;
                    end
                    configureModuleTabActiveState(app, t, app.Data.nodes(idx), activeIds);
                catch
                end
            end
        end

        function rebuildModuleTabsByType(app, nodeType)
            for nodeIdx = 1:numel(app.Data.nodes)
                node = app.Data.nodes(nodeIdx);
                if ~strcmpi(char(string(getField(app, node, 'type', ''))), nodeType)
                    continue;
                end
                nodeId = char(string(getField(app, node, 'id', '')));
                for tabIdx = 1:numel(app.DynamicModuleTabs)
                    try
                        t = app.DynamicModuleTabs(tabIdx);
                        if isvalid(t) && isstruct(t.UserData) && isfield(t.UserData, 'nodeId') && strcmp(char(string(t.UserData.nodeId)), nodeId)
                            delete(t.Children);
                            buildModuleTab(app, t, node);
                        end
                    catch
                    end
                end
            end
        end

        function deleteDynamicModuleTabs(app)
            if isempty(app.DynamicModuleTabs)
                return;
            end
            for i = 1:numel(app.DynamicModuleTabs)
                try
                    if isvalid(app.DynamicModuleTabs(i))
                        delete(app.DynamicModuleTabs(i));
                    end
                catch
                end
            end
            app.DynamicModuleTabs = gobjects(0);
        end

        function buildModuleTab(app, parentTab, node)
            if isRoiDefinitionNode(app, node)
                buildRoiDefinitionModuleTab(app, parentTab, node);
                return;
            end
            if strcmpi(char(string(getField(app, node, 'type', ''))), 'dataLoader')
                buildDataLoaderTab(app, parentTab, node);
                return;
            end
            isRoiExtractNode = strcmpi(char(string(getField(app, node, 'type', ''))), 'roiExtract');
            if isRoiExtractNode
                bindingData = cell(0, 6);
            else
                bindingData = bindingTableData(app, node);
            end
            staticData = paramsToTableData(app, node, 'static');
            runtimeData = paramsToTableData(app, node, 'runtime');
            referencePolicy = pipelineClassifierReferencePolicy(node);
            showClassifierReference = isClassifierNode(app, node) && referencePolicy.showReference;
            showPluginReference = isPluginPackageNode(app, node);
            showContractNotice = isClassifierNode(app, node) && referencePolicy.declared && ...
                ~referencePolicy.required && ~referencePolicy.semanticContractDeclared && ...
                referencePolicy.specAvailable && isfield(referencePolicy.spec, 'inputKeys') && ...
                ~isempty(referencePolicy.spec.inputKeys);
            showBindings = isRoiExtractNode || ~isempty(bindingData);
            showStatic = ~isempty(staticData);
            showRuntime = ~isempty(runtimeData);

            if ~showClassifierReference && ~showPluginReference && ~showContractNotice && ~showBindings && ~showStatic && ~showRuntime
                grid = uigridlayout(parentTab, [1 1]);
                grid.Padding = [12 10 12 12];
                uilabel(grid, 'Text', 'No module-specific parameters for this module.', ...
                    'FontAngle', 'italic', 'FontColor', [0.35 0.35 0.35]);
                return;
            end

            colCount = 1;
            rowCount = 2 * double(showClassifierReference) + 2 * double(showPluginReference) + double(showContractNotice) + 2 * double(showBindings) + ...
                2 * double(showRuntime) + double(showStatic);
            grid = uigridlayout(parentTab, [rowCount colCount]);
            rowHeights = {};
            if showClassifierReference
                rowHeights = [rowHeights {24, 76}]; %#ok<AGROW>
            end
            if showPluginReference
                rowHeights = [rowHeights {24, 76}]; %#ok<AGROW>
            end
            if showContractNotice
                rowHeights = [rowHeights {52}]; %#ok<AGROW>
            end
            if showBindings
                if isRoiExtractNode
                    rowHeights = [rowHeights {24, 210}]; %#ok<AGROW>
                else
                    rowHeights = [rowHeights {24, min(360, bindingSectionPreferredHeight(app, bindingData))}]; %#ok<AGROW>
                end
            end
            if showRuntime
                rowHeights = [rowHeights {24, '1x'}]; %#ok<AGROW>
            end
            if showStatic
                rowHeights = [rowHeights {26}]; %#ok<AGROW>
            end
            grid.RowHeight = rowHeights;
            grid.ColumnWidth = {'1x'};
            grid.Padding = [12 10 12 12];
            grid.ColumnSpacing = 16;
            grid.RowSpacing = 8;

            row = 1;
            if showClassifierReference
                referenceTitle = 'Trained classifier';
                if ~referencePolicy.required
                    referenceTitle = 'Trained classifier (optional)';
                end
                refLabel = uilabel(grid, 'Text', referenceTitle);
                refLabel.FontWeight = 'bold';
                refLabel.Layout.Row = row;
                refLabel.Layout.Column = layoutSpan(app, 1, colCount);

                section = buildClassifierReferenceSection(app, grid, node);
                section.Layout.Row = row + 1;
                section.Layout.Column = layoutSpan(app, 1, colCount);
                row = row + 2;
            end

            if showPluginReference
                pluginLabel = uilabel(grid, 'Text', 'Module package');
                pluginLabel.FontWeight = 'bold';
                pluginLabel.Layout.Row = row;
                pluginLabel.Layout.Column = layoutSpan(app, 1, colCount);

                section = buildPluginReferenceSection(app, grid, node);
                section.Layout.Row = row + 1;
                section.Layout.Column = layoutSpan(app, 1, colCount);
                row = row + 2;
            end

            if showContractNotice
                notice = uilabel(grid, 'Text', ...
                    ['This legacy adapter does not declare its detailed pipeline inputs and outputs. ' ...
                     'The generic bindings below are compatibility placeholders. Choose an adapter with ' ...
                     'a pipeline contract to configure the module inputs and outputs.'], ...
                    'WordWrap', 'on', 'FontColor', [0.62 0.32 0.08], ...
                    'Tag', 'PipelineModuleContractNotice');
                notice.Layout.Row = row;
                row = row + 1;
            end

            if showBindings
                bindingLabel = uilabel(grid, 'Text', 'Bindings');
                bindingLabel.FontWeight = 'bold';
                bindingLabel.Layout.Row = row;
                bindingLabel.Layout.Column = layoutSpan(app, 1, colCount);

                if isRoiExtractNode
                    section = buildRoiExtractBindingSection(app, grid, node);
                else
                    section = buildBindingSection(app, grid, bindingData, node, true);
                end
                section.Layout.Row = row + 1;
                section.Layout.Column = layoutSpan(app, 1, colCount);
                row = row + 2;
            end

            if showRuntime
                runtimeLabel = uilabel(grid, 'Text', 'Runtime parameters');
                runtimeLabel.FontWeight = 'bold';
                runtimeLabel.Layout.Row = row;
                runtimeLabel.Layout.Column = 1;

                section = buildParamSection(app, grid, runtimeData, node, app.Data.runMode, 'runtime');
                section.Layout.Row = row + 1;
                section.Layout.Column = 1;
                row = row + 2;
            end

            if showStatic
                staticPanel = uigridlayout(grid, [1 3]);
                staticPanel.ColumnWidth = {150, '1x', 170};
                staticPanel.RowHeight = {24};
                staticPanel.Padding = [0 0 0 0];
                staticPanel.ColumnSpacing = 8;
                staticPanel.Layout.Row = row;
                staticPanel.Layout.Column = 1;

                label = uilabel(staticPanel, 'Text', staticParamSectionTitle(app, node));
                label.FontWeight = 'bold';
                label.Layout.Row = 1;
                label.Layout.Column = 1;

                summary = uilabel(staticPanel, 'Text', staticParamSummary(app, node, staticData), ...
                    'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
                summary.Layout.Row = 1;
                summary.Layout.Column = 2;

                btn = uibutton(staticPanel, 'push', 'Text', staticParamButtonText(app, node), ...
                    'ButtonPushedFcn', @(~,~)app.openStaticParametersDialog(node));
                btn.Layout.Row = 1;
                btn.Layout.Column = 3;
            end
        end

        function tf = isRoiDefinitionNode(app, node) %#ok<INUSD>
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            tf = any(strcmp(nodeType, {'roipattern','roiidentify','roimanual','roigrid','roitracked'}));
        end

        function buildRoiDefinitionModuleTab(app, parentTab, node)
            bindingData = bindingTableData(app, node);
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            if strcmp(nodeType, 'roitracked') && ~isempty(bindingData)
                keep = ~strcmp(cellstr(string(bindingData(:,3))), 'extractChannels');
                bindingData = bindingData(keep, :);
            end
            showBindings = ~isempty(bindingData);
            rowCount = 2 * double(showBindings) + 2;
            grid = uigridlayout(parentTab, [rowCount 1]);
            rowHeights = {};
            if showBindings
                rowHeights = [rowHeights {24, min(220, bindingSectionPreferredHeight(app, bindingData))}]; %#ok<AGROW>
            end
            rowHeights = [rowHeights {24, '1x'}];
            grid.RowHeight = rowHeights;
            grid.ColumnWidth = {'1x'};
            grid.Padding = [12 10 12 12];
            grid.RowSpacing = 8;

            row = 1;
            if showBindings
                bindingLabel = uilabel(grid, 'Text', 'Bindings');
                bindingLabel.FontWeight = 'bold';
                bindingLabel.Layout.Row = row;
                section = buildBindingSection(app, grid, bindingData, node, true);
                section.Layout.Row = row + 1;
                row = row + 2;
            end

            titleLabel = uilabel(grid, 'Text', roiDefinitionTitle(app, node));
            titleLabel.FontWeight = 'bold';
            titleLabel.Layout.Row = row;

            body = uipanel(grid, 'BorderType', 'none');
            body.Layout.Row = row + 1;
            switch nodeType
                case {'roipattern','roiidentify'}
                    buildRoiPatternTab(app, body, node);
                case 'roigrid'
                    buildRoiGridTab(app, body, node);
                case 'roimanual'
                    buildRoiManualTab(app, body, node);
                case 'roitracked'
                    buildRoiTrackedTab(app, body, node);
                otherwise
                    buildGenericRoiDefinitionTab(app, body, node);
            end
        end

        function titleText = roiDefinitionTitle(app, node) %#ok<INUSD>
            switch lower(char(string(getField(app, node, 'type', ''))))
                case {'roipattern','roiidentify'}
                    titleText = 'ROI pattern definition';
                case 'roigrid'
                    titleText = 'ROI grid definition';
                case 'roimanual'
                    titleText = 'Manual ROI definition';
                case 'roitracked'
                    titleText = 'Tracked ROI definition';
                otherwise
                    titleText = 'ROI definition';
            end
        end

        function buildDataLoaderTab(app, parentTab, node) %#ok<INUSD>
            grid = uigridlayout(parentTab, [4 1]);
            grid.RowHeight = {24, 48, 24, '1x'};
            grid.Padding = [12 10 12 12];
            grid.RowSpacing = 8;

            title = uilabel(grid, 'Text', 'Runtime parser outputs');
            title.FontWeight = 'bold';
            title.Layout.Row = 1;

            mode = getRuntimeValue(app, 'inputSourceMode');
            if isempty(mode), mode = 'existing_rois'; end
            rawPath = getRuntimeValue(app, 'rawDataPath');
            if isempty(rawPath), rawPath = '<not selected>'; end
            modeText = 'Read from existing project';
            if strcmpi(mode, 'raw_dataloader')
                modeText = 'Parse raw images into project';
            end
            summary = uilabel(grid, 'Text', sprintf('Input mode: %s\nRaw image folder: %s', modeText, rawPath), ...
                'Interpreter', 'none', 'FontColor', [0.25 0.25 0.25]);
            summary.Layout.Row = 2;

            invLabel = uilabel(grid, 'Text', 'Detected inventory');
            invLabel.FontWeight = 'bold';
            invLabel.Layout.Row = 3;

            lines = {'Dataloader either exposes saved project FOVs or parses the raw image folder, depending on Input mode.'};
            if runtimeStartsFromExistingProject(app)
                rawStartNodeIds = selectedRunNodeIdsByType(app, {'dataloader','roigrid','roiidentify','roimanual','roipattern','roiextract'});
                if any(strcmp(rawStartNodeIds, char(string(getField(app, node, 'id', '')))))
                    rawLink = effectiveRuntimeRawDataPath(app);
                    if isempty(rawLink)
                        lines{end+1} = 'Active: this run will use the selected project as target, but no project raw data link was found yet.';
                    else
                        lines{end+1} = ['Active: this run will use the selected project raw data link: ' rawLink];
                    end
                else
                    lines{end+1} = 'Inactive: this node is not selected for this run.';
                end
            end
            if isfield(app.RuntimeParseInfo, 'fovCount') && ~isempty(app.RuntimeParseInfo.fovCount)
                lines{end+1} = sprintf('FOVs: 1:%d', app.RuntimeParseInfo.fovCount);
            end
            if isfield(app.RuntimeParseInfo, 'maxFrame') && ~isempty(app.RuntimeParseInfo.maxFrame)
                lines{end+1} = sprintf('Frames: 1:%d', round(double(app.RuntimeParseInfo.maxFrame)));
            end
            channels = runtimeConcreteChannels(app);
            if isempty(channels)
                lines{end+1} = 'Channels: none detected yet';
            else
                lines{end+1} = ['Channels: ' strjoin(channels, ', ')];
            end
            dsNames = runtimeDataSeriesNames(app);
            if ~isempty(dsNames)
                maxShown = min(numel(dsNames), 12);
                lines{end+1} = ['Project dataseries: ' strjoin(dsNames(1:maxShown), ', ')];
            end

            txt = uitextarea(grid, 'Editable', 'off', 'Value', lines);
            txt.Layout.Row = 4;
        end

        function tf = isClassifierNode(app, node) %#ok<INUSD>
            tf = strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier');
        end

        function tf = isPluginPackageNode(app, node)
            tf = false;
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            if ~any(strcmp(nodeType, {'processor','classifier'}))
                return;
            end
            if hasCustomPackageReference(app, node)
                tf = true;
                return;
            end
            pkg = char(string(getField(app, node, 'pkg', '')));
            if isempty(pkg)
                return;
            end
            tf = pluginPackageExists(app, pkg, nodeType);
        end

        function tf = hasCustomPackageReference(app, node) %#ok<INUSD>
            tf = false;
            try
                if isfield(node, 'customPackageRoot') && ~isempty(node.customPackageRoot)
                    tf = true;
                    return;
                end
                if isfield(node, 'customPackageDir') && ~isempty(node.customPackageDir)
                    tf = true;
                    return;
                end
                p = getField(app, node, 'params', struct());
                tf = isstruct(p) && ((isfield(p, 'customPackageRoot') && ~isempty(p.customPackageRoot)) || ...
                    (isfield(p, 'customPackageDir') && ~isempty(p.customPackageDir)));
            catch
                tf = false;
            end
        end

        function tf = pluginPackageExists(app, pkg, nodeType)
            tf = ~isempty(registeredPluginPackageDir(app, pkg, nodeType));
        end

        function txt = staticParamSectionTitle(app, node) %#ok<INUSD>
            if strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier')
                txt = 'Execution parameters';
            else
                txt = 'Static parameters';
            end
        end

        function txt = staticParamButtonText(app, node) %#ok<INUSD>
            if strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier')
                txt = 'Execution parameters...';
            else
                txt = 'Static parameters...';
            end
        end

        function txt = staticParamSummary(app, node, staticData) %#ok<INUSD>
            n = size(staticData, 1);
            if strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier')
                if n == 0
                    txt = 'No execution parameter.';
                elseif n == 1
                    txt = '1 pipeline execution override';
                else
                    txt = sprintf('%d pipeline execution overrides', n);
                end
                return;
            end
            if n == 0
                txt = 'No static parameter.';
            elseif n == 1
                txt = '1 template parameter';
            else
                txt = sprintf('%d template parameters', n);
            end
        end

        function openStaticParametersDialog(app, node)
            discoverRuntimeRoiInventory(app);
            nodeId = char(string(getField(app, node, 'id', 'module')));
            titleText = staticParamSectionTitle(app, node);
            fig = uifigure('Name', [titleText ' - ' nodeId], ...
                'Position', staticParametersDialogPosition(app, node));
            grid = uigridlayout(fig, [3 1]);
            grid.RowHeight = {34, '1x', 24};
            grid.Padding = [12 12 12 12];
            grid.RowSpacing = 10;

            headerBar = uigridlayout(grid, [1 5]);
            headerBar.ColumnWidth = {'1x', 110, 110, 100, 90};
            headerBar.RowHeight = {28};
            headerBar.Padding = [0 0 0 0];
            headerBar.ColumnSpacing = 8;
            headerBar.Layout.Row = 1;

            header = uilabel(headerBar, 'Text', staticParametersDialogHeader(app, node), 'Interpreter', 'none');
            header.FontWeight = 'bold';
            header.Layout.Row = 1;
            header.Layout.Column = 1;

            defaultsBtn = uibutton(headerBar, 'push', 'Text', 'Fill missing', ...
                'ButtonPushedFcn', @(~,~)app.refreshModuleDefaultsAndDialog(fig, nodeId));
            defaultsBtn.Layout.Row = 1;
            defaultsBtn.Layout.Column = 2;

            resetBtn = uibutton(headerBar, 'push', 'Text', 'Reset defaults', ...
                'ButtonPushedFcn', @(~,~)app.resetModuleDefaultsAndDialog(fig, nodeId));
            resetBtn.Layout.Row = 1;
            resetBtn.Layout.Column = 3;

            refreshBtn = uibutton(headerBar, 'push', 'Text', 'Refresh UI', ...
                'ButtonPushedFcn', @(~,~)app.refreshStaticParametersDialog(fig, nodeId));
            refreshBtn.Layout.Row = 1;
            refreshBtn.Layout.Column = 4;

            closeBtn = uibutton(headerBar, 'push', 'Text', 'Close', ...
                'ButtonPushedFcn', @(~,~)delete(fig));
            closeBtn.Layout.Row = 1;
            closeBtn.Layout.Column = 5;

            bodyPanel = uipanel(grid, 'BorderType', 'none');
            bodyPanel.Layout.Row = 2;
            try
                bodyPanel.Scrollable = 'on';
            catch
            end
            fig.UserData = struct('nodeId', nodeId, 'bodyPanel', bodyPanel, ...
                'refreshButton', refreshBtn, 'defaultsButton', defaultsBtn, 'resetButton', resetBtn);
            populateStaticParametersDialogBody(app, bodyPanel, nodeId);

            hint = uilabel(grid, 'Text', staticParametersDialogHint(app, node), ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 3;
        end

        function txt = staticParametersDialogHeader(app, node)
            nodeId = char(string(getField(app, node, 'id', 'module')));
            if strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier')
                txt = ['Pipeline execution parameters for ' nodeId];
            else
                txt = ['Static/template parameters for ' nodeId];
            end
        end

        function txt = staticParametersDialogHint(app, node)
            if strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier')
                policy = pipelineClassifierReferencePolicy(node);
                if policy.declared && ~policy.required
                    txt = 'Module settings, model releases and environment paths are saved in this pipeline; no trained classifier object is required.';
                else
                    txt = ['These values are the effective run-time settings saved in the pipeline. ' ...
                        'The linked classifier GUI remains the place for training data, training defaults and model artifacts.'];
                end
            else
                txt = 'Changes are saved in the pipeline template.';
            end
        end

        function refreshStaticParametersDialog(app, fig, nodeId)
            if isempty(fig) || ~isvalid(fig)
                return;
            end
            focus = captureTabFocus(app);
            d = openRuntimeProgress(app, 'Static parameters', 'Refreshing pipeline tabs...');
            drawnow limitrate nocallbacks;
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                closeRuntimeProgress(app, d);
                return;
            end
            bodyPanel = [];
            try
                if isstruct(fig.UserData) && isfield(fig.UserData, 'bodyPanel') && isvalid(fig.UserData.bodyPanel)
                    bodyPanel = fig.UserData.bodyPanel;
                end
            catch
                bodyPanel = [];
            end
            if isempty(bodyPanel)
                closeRuntimeProgress(app, d);
                return;
            end
            refreshBtn = [];
            try
                if isstruct(fig.UserData) && isfield(fig.UserData, 'refreshButton') && isvalid(fig.UserData.refreshButton)
                    refreshBtn = fig.UserData.refreshButton;
                    refreshBtn.Enable = 'off';
                end
            catch
                refreshBtn = [];
            end
            cleanupObj = onCleanup(@()finishStaticParameterRefresh(app, d, refreshBtn, focus)); %#ok<NASGU>
            updateRuntimeProgress(app, d, 'Rebuilding pipeline module tabs...');
            refreshModuleTabs(app);
            updateRuntimeProgress(app, d, 'Checking pipeline bindings...');
            refreshValidationReport(app);
            updateRuntimeProgress(app, d, 'Refreshing static parameter editor...');
            populateStaticParametersDialogBody(app, bodyPanel, nodeId);
            resizeStaticParametersDialog(app, fig, app.Data.nodes(idx));
            drawnow limitrate nocallbacks;
        end

        function refreshModuleDefaultsAndDialog(app, fig, nodeId)
            if isempty(fig) || ~isvalid(fig)
                return;
            end
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                return;
            end
            d = openRuntimeProgress(app, 'Static parameters', 'Refreshing module defaults...');
            btn = [];
            try
                if isstruct(fig.UserData) && isfield(fig.UserData, 'defaultsButton') && isvalid(fig.UserData.defaultsButton)
                    btn = fig.UserData.defaultsButton;
                    btn.Enable = 'off';
                end
            catch
                btn = [];
            end
            cleanupObj = onCleanup(@()finishStaticParameterRefresh(app, d, btn, captureTabFocus(app))); %#ok<NASGU>
            try
                [added, updated] = refreshNodeDefaultsFromModule(app, idx);
                markPipelineDirty(app, true);
                updateRuntimeProgress(app, d, sprintf('Added %d missing default(s).', added));
                refreshModuleTabs(app);
                refreshValidationReport(app);
                populateStaticParametersDialogBody(app, fig.UserData.bodyPanel, nodeId);
                resizeStaticParametersDialog(app, fig, app.Data.nodes(idx));
                if added == 0 && updated == 0
                    setRuntimeStatus(app, ['Module defaults already current: ' nodeId]);
                else
                    setRuntimeStatus(app, sprintf('Module defaults refreshed for %s: %d field(s) added.', nodeId, added));
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Refresh module defaults', 'Icon', 'error');
            end
            drawnow limitrate nocallbacks;
        end

        function resetModuleDefaultsAndDialog(app, fig, nodeId)
            if isempty(fig) || ~isvalid(fig)
                return;
            end
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                return;
            end
            d = openRuntimeProgress(app, 'Static parameters', 'Resetting module defaults...');
            btn = [];
            try
                if isstruct(fig.UserData) && isfield(fig.UserData, 'resetButton') && isvalid(fig.UserData.resetButton)
                    btn = fig.UserData.resetButton;
                    btn.Enable = 'off';
                end
            catch
                btn = [];
            end
            cleanupObj = onCleanup(@()finishStaticParameterRefresh(app, d, btn, captureTabFocus(app))); %#ok<NASGU>
            try
                [changed, total] = resetNodeDefaultsFromModule(app, idx);
                markPipelineDirty(app, true);
                updateRuntimeProgress(app, d, sprintf('Reset %d/%d default parameter(s).', changed, total));
                refreshModuleTabs(app);
                refreshValidationReport(app);
                populateStaticParametersDialogBody(app, fig.UserData.bodyPanel, nodeId);
                resizeStaticParametersDialog(app, fig, app.Data.nodes(idx));
                if changed == 0
                    setRuntimeStatus(app, ['Module static parameters already at defaults: ' nodeId]);
                else
                    setRuntimeStatus(app, sprintf('Module static parameters reset for %s: %d field(s) updated.', nodeId, changed));
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Reset module defaults', 'Icon', 'error');
            end
            drawnow limitrate nocallbacks;
        end

        function [added, updated] = refreshNodeDefaultsFromModule(app, idx)
            added = 0;
            updated = 0;
            if idx < 1 || idx > numel(app.Data.nodes)
                return;
            end
            node = app.Data.nodes(idx);
            nodeType = char(string(getField(app, node, 'type', '')));
            pkg = char(string(getField(app, node, 'pkg', '')));
            if ~isfield(node, 'params') || ~isstruct(node.params)
                node.params = struct();
            end
            app.ensureCustomPackagePathForNode(node);
            defaults = defaultNodeParams(app, nodeType, pkg);
            if ~isstruct(defaults) || isempty(fieldnames(defaults))
                return;
            end
            keys = moduleParamKeys(app, node, 'static');
            keys = keys(ismember(keys, fieldnames(defaults)));
            before = node.params;
            for i = 1:numel(keys)
                key = keys{i};
                if ~isfield(node.params, key) || isempty(node.params.(key))
                    node.params.(key) = defaults.(key);
                    added = added + 1;
                elseif isChoiceListValue(app, node.params.(key)) && isChoiceListValue(app, defaults.(key))
                    refreshed = refreshChoiceListKeepingSelection(app, node.params.(key), defaults.(key));
                    if ~isequaln(refreshed, node.params.(key))
                        node.params.(key) = refreshed;
                    end
                end
            end
            app.Data.nodes(idx) = alignNodeForAssignment(app, app.Data.nodes(idx), node);
            updated = double(~isequaln(before, node.params));
        end

        function tf = isChoiceListValue(app, value) %#ok<INUSD>
            tf = iscell(value) && numel(value) > 1;
        end

        function value = refreshChoiceListKeepingSelection(app, currentValue, defaultValue)
            selected = choiceScalarText(app, currentValue);
            choices = defaultValue(:)';
            choices = choices(~cellfun(@isempty, choices));
            if isempty(choices)
                value = currentValue;
                return;
            end
            defaultSelected = choiceScalarText(app, defaultValue);
            choiceLabels = choices;
            if ~isempty(defaultSelected) && numel(choiceLabels) > 1 && strcmp(char(string(choiceLabels{end})), defaultSelected)
                choiceLabels = choiceLabels(1:end-1);
            end
            if isempty(selected) || ~any(strcmp(cellstr(string(choiceLabels)), selected))
                selected = defaultSelected;
            end
            if isempty(selected)
                value = defaultValue;
            else
                value = [choiceLabels {selected}];
            end
        end

        function [changed, total] = resetNodeDefaultsFromModule(app, idx)
            changed = 0;
            total = 0;
            if idx < 1 || idx > numel(app.Data.nodes)
                return;
            end
            node = app.Data.nodes(idx);
            nodeType = char(string(getField(app, node, 'type', '')));
            pkg = char(string(getField(app, node, 'pkg', '')));
            if ~isfield(node, 'params') || ~isstruct(node.params)
                node.params = struct();
            end
            app.ensureCustomPackagePathForNode(node);
            defaults = defaultNodeParams(app, nodeType, pkg);
            if ~isstruct(defaults) || isempty(fieldnames(defaults))
                return;
            end
            keys = moduleParamKeys(app, node, 'static');
            keys = keys(ismember(keys, fieldnames(defaults)));
            total = numel(keys);
            for i = 1:numel(keys)
                key = keys{i};
                oldValue = [];
                hadOldValue = isfield(node.params, key);
                if hadOldValue
                    oldValue = node.params.(key);
                end
                node.params.(key) = defaults.(key);
                if ~hadOldValue || ~isequaln(oldValue, defaults.(key))
                    changed = changed + 1;
                end
            end
            app.Data.nodes(idx) = alignNodeForAssignment(app, app.Data.nodes(idx), node);
        end

        function ensureCustomPackagePathForNode(app, node) %#ok<INUSD>
            try
                root = customPackageRootForNode(app, node);
                if isfolder(root) && ~contains(path, root)
                    addpath(root);
                    rehash;
                end
            catch
            end
        end

        function root = customPackageRootForNode(app, node) %#ok<INUSD>
            root = '';
            try
                if isstruct(node) && isfield(node, 'customPackageRoot') && ~isempty(node.customPackageRoot)
                    root = char(string(node.customPackageRoot));
                    return;
                end
                p = getField(app, node, 'params', struct());
                if isstruct(p) && isfield(p, 'customPackageRoot') && ~isempty(p.customPackageRoot)
                    root = char(string(p.customPackageRoot));
                end
            catch
                root = '';
            end
        end

        function node = alignNodeForAssignment(app, oldNode, node) %#ok<INUSD>
            oldFields = fieldnames(oldNode);
            newFields = fieldnames(node);
            for i = 1:numel(oldFields)
                if ~isfield(node, oldFields{i})
                    node.(oldFields{i}) = oldNode.(oldFields{i});
                end
            end
            for i = 1:numel(newFields)
                if ~isfield(oldNode, newFields{i})
                    oldNode.(newFields{i}) = node.(newFields{i});
                end
            end
            node = orderfields(node, oldNode);
        end

        function assignNodeForAssignment(app, idx, node)
            if isempty(idx) || idx < 1 || idx > numel(app.Data.nodes)
                return;
            end
            nodes = app.Data.nodes;
            oldNode = nodes(idx);
            allFields = unique([fieldnames(nodes); fieldnames(node)], 'stable');
            for i = 1:numel(allFields)
                f = allFields{i};
                if ~isfield(nodes, f)
                    [nodes.(f)] = deal([]);
                end
                if ~isfield(node, f)
                    node.(f) = [];
                end
            end
            node = alignNodeForAssignment(app, oldNode, node);
            nodes(idx) = node;
            app.Data.nodes = nodes;
        end

        function finishStaticParameterRefresh(app, progressDlg, refreshBtn, focus)
            try
                closeRuntimeProgress(app, progressDlg);
            catch
            end
            try
                if ~isempty(refreshBtn) && isvalid(refreshBtn)
                    refreshBtn.Enable = 'on';
                end
            catch
            end
            try
                restoreTabFocus(app, focus);
            catch
            end
        end

        function populateStaticParametersDialogBody(app, bodyPanel, nodeId)
            try
                delete(bodyPanel.Children);
            catch
            end
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                grid = uigridlayout(bodyPanel, [1 1]);
                grid.Padding = [0 0 0 0];
                uilabel(grid, 'Text', 'Module no longer exists.', ...
                    'FontAngle', 'italic', 'FontColor', [0.35 0.35 0.35]);
                return;
            end
            node = app.Data.nodes(idx);
            staticData = paramsToTableData(app, node, 'static');
            if isempty(staticData)
                grid = uigridlayout(bodyPanel, [1 1]);
                grid.Padding = [0 0 0 0];
                uilabel(grid, 'Text', 'No static parameter is declared for this module.', ...
                    'FontAngle', 'italic', 'FontColor', [0.35 0.35 0.35]);
            else
                buildParamSection(app, bodyPanel, staticData, node, true, 'static');
            end
        end

        function pos = staticParametersDialogPosition(app, node)
            staticData = paramsToTableData(app, node, 'static');
            nParams = max(1, size(staticData, 1));
            rowHeight = 28;
            rowSpacing = 6;
            bodyHeight = nParams * rowHeight + max(0, nParams - 1) * rowSpacing + 18;
            desiredHeight = 34 + bodyHeight + 24 + 48;
            desiredWidth = 900;
            try
                screen = get(0, 'ScreenSize');
            catch
                screen = [1 1 1280 800];
            end
            maxWidth = max(760, screen(3) - 80);
            maxHeight = max(520, screen(4) - 110);
            width = min(max(820, desiredWidth), maxWidth);
            height = min(max(420, desiredHeight), maxHeight);

            base = [];
            try
                if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                    base = app.UIFigure.Position;
                end
            catch
                base = [];
            end
            if isempty(base)
                x = screen(1) + (screen(3) - width) / 2;
                y = screen(2) + (screen(4) - height) / 2;
            else
                x = base(1) + (base(3) - width) / 2;
                y = base(2) + (base(4) - height) / 2;
            end
            x = max(screen(1) + 20, min(x, screen(1) + screen(3) - width - 20));
            y = max(screen(2) + 40, min(y, screen(2) + screen(4) - height - 60));
            pos = [x y width height];
        end

        function resizeStaticParametersDialog(app, fig, node)
            if isempty(fig) || ~isvalid(fig)
                return;
            end
            target = staticParametersDialogPosition(app, node);
            pos = fig.Position;
            if pos(3) < target(3) || pos(4) < target(4)
                fig.Position = [pos(1) pos(2) max(pos(3), target(3)) max(pos(4), target(4))];
            end
        end

        function section = buildClassifierReferenceSection(app, parent, node)
            section = uigridlayout(parent, [2 5]);
            section.RowHeight = {24, 28};
            section.ColumnWidth = {'1x', 150, 150, 170, 110};
            section.Padding = [0 0 0 0];
            section.RowSpacing = 6;
            section.ColumnSpacing = 8;

            status = uilabel(section, 'Text', classifierReferenceSummary(app, node), ...
                'FontColor', classifierReferenceColor(app, node), 'Interpreter', 'none');
            status.Layout.Row = 1;
            status.Layout.Column = [1 5];

            hint = uilabel(section, 'Text', ['Choose a classifier folder; execution parameters below ' ...
                'are the values used by runs.'], ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 2;
            hint.Layout.Column = 1;

            linkButton = uibutton(section, 'push', 'Text', 'Link classifier...', ...
                'ButtonPushedFcn', @(~,~)linkClassifierArtifact(app, node));
            linkButton.Tooltip = 'Select a classifier folder or a cellLatentModel runtime bundle folder.';
            linkButton.Layout.Row = 2;
            linkButton.Layout.Column = 2;

            importButton = uibutton(section, 'push', 'Text', 'Import defaults...', ...
                'ButtonPushedFcn', @(~,~)importClassifierDefaults(app, node));
            importButton.Tooltip = 'Copy execution defaults from the linked classifier into this pipeline node.';
            importButton.Layout.Row = 2;
            importButton.Layout.Column = 3;

            openButton = uibutton(section, 'push', 'Text', 'Open linked classifier', ...
                'ButtonPushedFcn', @(~,~)openLinkedClassifier(app, node));
            openButton.Layout.Row = 2;
            openButton.Layout.Column = 4;

            clearButton = uibutton(section, 'push', 'Text', 'Clear link', ...
                'ButtonPushedFcn', @(~,~)clearClassifierArtifactLink(app, node));
            clearButton.Layout.Row = 2;
            clearButton.Layout.Column = 5;
        end

        function section = buildPluginReferenceSection(app, parent, node)
            section = uigridlayout(parent, [2 4]);
            section.RowHeight = {24, 28};
            section.ColumnWidth = {'1x', 150, 150, 150};
            section.Padding = [0 0 0 0];
            section.RowSpacing = 6;
            section.ColumnSpacing = 8;

            status = uilabel(section, 'Text', pluginReferenceSummary(app, node), ...
                'FontColor', pluginReferenceColor(app, node), 'Interpreter', 'none');
            status.Tooltip = status.Text;
            status.Layout.Row = 1;
            status.Layout.Column = [1 4];

            hint = uilabel(section, 'Text', ...
                'Code package used by this module.', ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 2;
            hint.Layout.Column = 1;

            relinkButton = uibutton(section, 'push', 'Text', 'Choose module...', ...
                'ButtonPushedFcn', @(~,~)relinkPluginPackage(app, node));
            relinkButton.Layout.Row = 2;
            relinkButton.Layout.Column = 2;

            browseButton = uibutton(section, 'push', 'Text', 'Module catalogue...', ...
                'ButtonPushedFcn', @(~,~)openPluginBrowser(app));
            browseButton.Layout.Row = 2;
            browseButton.Layout.Column = 3;

            clearButton = uibutton(section, 'push', 'Text', 'Use installed module', ...
                'ButtonPushedFcn', @(~,~)clearPluginPackageLink(app, node));
            clearButton.Tooltip = 'Replace the linked copy with the installed DetecDiv-plugins module, keeping analysis settings.';
            clearButton.Enable = ternary(app, hasCustomPackageReference(app, node), 'on', 'off');
            clearButton.Layout.Row = 2;
            clearButton.Layout.Column = 4;
        end

        function txt = pluginReferenceSummary(app, node)
            info = pluginReferenceInfo(app, node);
            txt = info.summary;
        end

        function color = pluginReferenceColor(app, node)
            info = pluginReferenceInfo(app, node);
            color = info.color;
        end

        function info = pluginReferenceInfo(app, node)
            pkg = char(string(getField(app, node, 'pkg', '')));
            nodeType = char(string(getField(app, node, 'type', '')));
            info = struct('summary', '', 'color', [0.62 0.32 0.08]);
            [packageRoot, packageDir] = customPackagePathsForNode(app, node);
            if ~isempty(packageDir)
                if exist(packageDir, 'dir') == 7
                    info.summary = ['Pipeline module: ' pkg '  |  ' packageDir];
                    info.color = [0.10 0.42 0.20];
                else
                    recovered = registeredPluginPackageDir(app, pkg, nodeType);
                    if ~isempty(recovered)
                        info.summary = ['Stored module path is unavailable; installed copy found: ' recovered];
                        info.color = [0.72 0.38 0.08];
                    else
                        info.summary = ['Pipeline module folder not accessible: ' packageDir];
                        info.color = [0.75 0.18 0.18];
                    end
                end
                return;
            end
            recovered = registeredPluginPackageDir(app, pkg, nodeType);
            if ~isempty(recovered)
                info.summary = ['Selected installed module: ' pkg '  |  ' recovered];
                info.color = [0.10 0.42 0.20];
            else
                info.summary = ['Module package unavailable: ' pkg];
                info.color = [0.75 0.18 0.18];
            end
            if ~isempty(packageRoot) && isempty(packageDir)
                info.summary = [info.summary '  |  root: ' packageRoot];
            end
        end

        function [packageRoot, packageDir] = customPackagePathsForNode(app, node)
            packageRoot = '';
            packageDir = '';
            try
                if isfield(node, 'customPackageRoot') && ~isempty(node.customPackageRoot)
                    packageRoot = char(string(node.customPackageRoot));
                end
                if isfield(node, 'customPackageDir') && ~isempty(node.customPackageDir)
                    packageDir = char(string(node.customPackageDir));
                end
                p = getField(app, node, 'params', struct());
                if isstruct(p)
                    if isempty(packageRoot) && isfield(p, 'customPackageRoot') && ~isempty(p.customPackageRoot)
                        packageRoot = char(string(p.customPackageRoot));
                    end
                    if isempty(packageDir) && isfield(p, 'customPackageDir') && ~isempty(p.customPackageDir)
                        packageDir = char(string(p.customPackageDir));
                    end
                end
                if isempty(packageDir) && ~isempty(packageRoot)
                    pkg = char(string(getField(app, node, 'pkg', '')));
                    if ~isempty(pkg)
                        packageDir = fullfile(packageRoot, ['+' pkg]);
                    end
                end
            catch
                packageRoot = '';
                packageDir = '';
            end
        end

        function packageDir = registeredPluginPackageDir(app, pkg, nodeType)
            packageDir = '';
            if isempty(pkg)
                return;
            end
            % Only report the copy actually selected by MATLAB. Catalogue
            % inspection must not promote another adapter onto the path.
            if strcmpi(nodeType, 'classifier')
                entrypoint = [char(string(pkg)) '.classify'];
            else
                entrypoint = [char(string(pkg)) '.process'];
            end
            resolved = which(entrypoint);
            if isempty(resolved)
                return;
            end
            selectedDir = fileparts(resolved);
            for i = 1:numel(app.ModuleCatalogueItems)
                module = app.ModuleCatalogueItems(i).module;
                if strcmp(module.pkg, char(string(pkg))) && ...
                        strcmpi(module.type, char(string(nodeType))) && ...
                        strcmp(module.distribution, 'external') && ...
                        sameModuleCataloguePath(app, module.path, selectedDir)
                    packageDir = module.path;
                    return;
                end
            end
        end

        function relinkPluginPackage(app, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                return;
            end
            choice = resolveCustomPackageChoice(app, 'Relink plugin package');
            if isempty(choice) || ~isstruct(choice)
                return;
            end
            expectedType = char(string(getField(app, app.Data.nodes(idx), 'type', '')));
            expectedPkg = char(string(getField(app, app.Data.nodes(idx), 'pkg', '')));
            if ~strcmpi(choice.type, expectedType)
                uialert(app.UIFigure, sprintf('This node expects a %s package, but the selected package is %s.', ...
                    expectedType, choice.type), 'Relink plugin package', 'Icon', 'warning');
                return;
            end
            if ~isempty(expectedPkg) && ~strcmp(choice.pkg, expectedPkg)
                answer = questdlg(sprintf('This node currently uses package "%s". Replace it with "%s"?', ...
                    expectedPkg, choice.pkg), 'Relink plugin package', 'Replace', 'Cancel', 'Cancel');
                if ~strcmp(answer, 'Replace')
                    return;
                end
            end
            updatedNode = app.Data.nodes(idx);
            updatedNode.pkg = choice.pkg;
            updatedNode.func = defaultNodeFunction(app, choice.type, choice.pkg);
            updatedNode.gui = defaultNodeGui(app, choice.type, choice.pkg);
            updatedNode = applyCustomPackagePatchToNode(app, updatedNode, choice.paramsPatch);
            updatedNode = pipelineNormalizeNodes(updatedNode, 'persist');
            assignNodeForAssignment(app, idx, updatedNode);
            refreshAfterModelChange(app);
            setRuntimeStatus(app, ['Plugin package relinked: ' choice.pkg]);
        end

        function clearPluginPackageLink(app, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                return;
            end
            try
                [updatedNode, runtimeParams, info] = detecdiv_plugins_reset_link( ...
                    app.Data.nodes(idx), getRuntimeNodeParams(app, nodeId));
            catch ME
                uialert(app.UIFigure, ME.message, 'Clear plugin link', 'Icon', 'warning');
                return;
            end
            assignNodeForAssignment(app, idx, updatedNode);
            setRuntimeNodeParams(app, nodeId, runtimeParams);
            refreshAfterModelChange(app);
            setRuntimeStatus(app, ['Using installed plugin: ' info.packageDir]);
        end

        function txt = classifierReferenceSummary(app, node)
            info = classifierReferenceInfo(app, node);
            txt = info.summary;
        end

        function color = classifierReferenceColor(app, node)
            info = classifierReferenceInfo(app, node);
            color = info.color;
        end

        function info = classifierReferenceInfo(app, node)
            expected = char(string(getField(app, node, 'pkg', '')));
            info = struct('summary', '', 'color', [0.62 0.32 0.08]);
            p = getField(app, node, 'params', struct());
            if ~isstruct(p) || ~isfield(p, 'modulePath') || isempty(p.modulePath)
                info.summary = ['No linked classifier object. Expected package: ' expected];
                if strcmpi(expected, 'cellposesam')
                    info.summary = [info.summary '. Default model will be used.'];
                end
                return;
            end

            modulePath = char(string(p.modulePath));
            moduleId = '';
            if isfield(p, 'moduleId') && ~isempty(p.moduleId)
                moduleId = char(string(p.moduleId));
            end
            if isempty(moduleId)
                [~, moduleId] = fileparts(modulePath);
            end

            target = runtimeExecutionTarget(app);
            folderOk = exist(modulePath, 'dir') == 7;
            snapshotOk = false;
            if folderOk
                snapshotOk = ~isempty(localClassifierSnapshotPath(app, modulePath, moduleId));
            end

            if strcmpi(target, 'hub') && localLooksLikeWindowsPath(app, modulePath)
                info.summary = ['Linked locally only for Hub: ' moduleId '  |  ' modulePath];
                info.color = [0.72 0.38 0.08];
                return;
            end

            if folderOk && snapshotOk
                info.summary = ['Linked: ' moduleId '  |  ' modulePath];
                info.color = [0.10 0.42 0.20];
            elseif folderOk
                info.summary = ['Linked folder found but classifier snapshot is missing: ' moduleId '  |  ' modulePath];
                info.color = [0.72 0.38 0.08];
            else
                info.summary = ['Linked classifier folder not accessible: ' moduleId '  |  ' modulePath];
                info.color = [0.75 0.18 0.18];
            end
        end

        function tf = localLooksLikeWindowsPath(app, pathText) %#ok<INUSD>
            pathText = char(string(pathText));
            tf = ~isempty(regexp(pathText, '^[A-Za-z]:[\\/]', 'once'));
        end

        function snap = localClassifierSnapshotPath(app, modulePath, moduleId) %#ok<INUSD>
            snap = '';
            candidates = {};
            if ~isempty(moduleId)
                candidates{end+1} = fullfile(modulePath, [moduleId '_classification.mat']); %#ok<AGROW>
            end
            files = dir(fullfile(modulePath, '*_classification.mat'));
            for i = 1:numel(files)
                candidates{end+1} = fullfile(files(i).folder, files(i).name); %#ok<AGROW>
            end
            for i = 1:numel(candidates)
                if exist(candidates{i}, 'file') == 2
                    snap = candidates{i};
                    return;
                end
            end
        end

        function linkClassifierArtifact(app, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                return;
            end
            startPath = pwd;
            try
                p = getField(app, app.Data.nodes(idx), 'params', struct());
                if isstruct(p) && isfield(p, 'modulePath') && ~isempty(p.modulePath)
                    startPath = char(string(p.modulePath));
                end
            catch
            end
            % When a runtime module is already linked, start at its bundle
            % root so runtime_manifest.json is immediately visible.
            try
                if exist(startPath, 'dir') == 7 && ...
                        exist(fullfile(startPath, 'runtime_manifest.json'), 'file') ~= 2
                    bundleRoot = fileparts(fileparts(startPath));
                    if exist(fullfile(bundleRoot, 'runtime_manifest.json'), 'file') == 2
                        startPath = bundleRoot;
                    end
                end
            catch
            end
            selectedFolder = uigetdir(startPath, 'Select classifier folder');
            if isequal(selectedFolder, 0)
                return;
            end
            selectedFolder = char(string(selectedFolder));
            linkProgress = [];
            try
                if exist(selectedFolder, 'dir') ~= 7
                    error('pipeline2:InvalidClassifierFolder', ...
                        'The selected path is not an accessible folder: %s', selectedFolder);
                end
                manifestPath = fullfile(selectedFolder, 'runtime_manifest.json');
                bundleReport = [];
                if exist(manifestPath, 'file') == 2
                    if ~strcmpi(char(string(getField(app, app.Data.nodes(idx), 'pkg', ''))), ...
                            'cellLatentModel')
                        error('pipeline2:ClassifierPackageMismatch', ...
                            ['This folder contains a latent-model runtime bundle, which can only be ' ...
                             'linked to a cellLatentModel node.']);
                    end
                    linkProgress = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Link latent-model runtime', ...
                        'Message', 'Reading runtime manifest...', ...
                        'Value', 0, 'Cancelable', 'off');
                    ddProgressGuard3 = onCleanup(@()detecdiv_close_progress(linkProgress)); %#ok<NASGU>
                    drawnow nocallbacks;
                    drawnow limitrate nocallbacks;
                    bundleReport = cellLatentModel.validateRuntimeManifest(selectedFolder, ...
                        @(fraction,message)updateClassifierLinkProgress( ...
                        app,linkProgress,fraction,message));
                    manifestClassiId = char(string(bundleReport.classifierId));
                    filePath = fullfile(selectedFolder, 'classifier', manifestClassiId, ...
                        [manifestClassiId '_classification.mat']);
                    if exist(filePath, 'file') ~= 2
                        error('pipeline2:RuntimeClassifierSnapshotMissing', ...
                            'The runtime manifest is valid, but its classifier snapshot is missing: %s', filePath);
                    end
                    updateClassifierLinkProgress(app,linkProgress,NaN, ...
                        'Loading the linked classifier snapshot...');
                else
                    snapshots = dir(fullfile(selectedFolder, '*_classification.mat'));
                    validObjects = {};
                    expectedPkg = char(string(getField(app, app.Data.nodes(idx), 'pkg', '')));
                    matchingObjects = {};
                    matchingFiles = {};
                    for candidateIndex = 1:numel(snapshots)
                        candidatePath = fullfile(snapshots(candidateIndex).folder, snapshots(candidateIndex).name);
                        if ~isempty(regexp(snapshots(candidateIndex).name, ...
                                '_classification_\d+\.mat$', 'once'))
                            continue;
                        end
                        [candidateObj, ~] = loadClassifierSnapshotStrict(app, candidatePath);
                        if isempty(candidateObj) || ~isa(candidateObj, 'classi')
                            continue;
                        end
                        validObjects{end+1} = candidateObj; %#ok<AGROW>
                        candidatePkg = classifierPackageName(app, candidateObj);
                        if isempty(expectedPkg) || isempty(candidatePkg) || ...
                                strcmpi(expectedPkg, candidatePkg)
                            matchingObjects{end+1} = candidateObj; %#ok<AGROW>
                            matchingFiles{end+1} = candidatePath; %#ok<AGROW>
                        end
                    end
                    if isempty(validObjects)
                        error('pipeline2:InvalidClassifierFolder', ...
                            ['The selected folder is not a valid classifier folder. It must contain ' ...
                             'a current *_classification.mat file with a classi object, or a valid ' ...
                             'runtime_manifest.json for a cellLatentModel bundle.' newline newline ...
                             'Folder: %s'], ...
                            selectedFolder);
                    end
                    if isempty(matchingObjects)
                        foundPackages = cellfun(@(obj)classifierPackageName(app, obj), ...
                            validObjects, 'UniformOutput', false);
                        foundPackages = unique(foundPackages(~cellfun(@isempty, foundPackages)));
                        error('pipeline2:ClassifierPackageMismatch', ...
                            'This node expects package "%s", but the selected folder contains classifier package(s): %s.', ...
                            expectedPkg, strjoin(foundPackages, ', '));
                    end
                    if numel(matchingObjects) > 1
                        error('pipeline2:AmbiguousClassifierFolder', ...
                            ['The selected folder contains multiple matching classifier snapshots. ' ...
                             'Choose the specific classifier folder. Candidates: %s'], ...
                            strjoin(matchingFiles, newline));
                    end
                    filePath = matchingFiles{1};
                end
                [classiObj, msg] = loadClassifierSnapshotStrict(app, filePath);
                if isempty(classiObj) || ~isa(classiObj, 'classi')
                    if isempty(msg), msg = 'Selected file is not a classi object.'; end
                    error('pipeline2:BadClassifierLink', '%s', msg);
                end
                if ~isempty(bundleReport) && ...
                        ~strcmp(char(string(classiObj.strid)), manifestClassiId)
                    error('pipeline2:RuntimeClassifierIdMismatch', ...
                        'The runtime manifest names classifier "%s", but the snapshot contains "%s".', ...
                        manifestClassiId, char(string(classiObj.strid)));
                end
                expectedPkg = char(string(getField(app, app.Data.nodes(idx), 'pkg', '')));
                actualPkg = classifierPackageName(app, classiObj);
                if ~isempty(expectedPkg) && ~isempty(actualPkg) && ~strcmpi(expectedPkg, actualPkg)
                    error('pipeline2:ClassifierPackageMismatch', ...
                        'This node expects package "%s", but the selected classifier uses "%s".', expectedPkg, actualPkg);
                end
                if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                    app.Data.nodes(idx).params = struct();
                end
                if ~isempty(bundleReport)
                    classiPath = fileparts(filePath);
                else
                    classiPath = selectedFolder;
                end
                classiId = char(string(classiObj.strid));
                app.Data.nodes(idx).params.modulePath = classiPath;
                app.Data.nodes(idx).params.moduleId = classiId;
                if ~isempty(actualPkg)
                    app.Data.nodes(idx).params.pkg = actualPkg;
                    app.Data.nodes(idx).pkg = actualPkg;
                    app.Data.nodes(idx).func = [actualPkg '.classify'];
                end
                executionPkg = actualPkg;
                if isempty(executionPkg)
                    executionPkg = expectedPkg;
                end
                if strcmpi(executionPkg, 'cellposesam')
                    app.Data.nodes(idx).params = copyCellposeStaticParamsFromClassi(app, app.Data.nodes(idx).params, classiObj);
                elseif strcmpi(executionPkg, 'sam31')
                    app.Data.nodes(idx).params = copySam31StaticParamsFromClassi(app, app.Data.nodes(idx).params, classiObj);
                elseif strcmpi(executionPkg, 'deeplab_pixel_classification')
                    app.Data.nodes(idx).params = copyDeeplabPixelStaticParamsFromClassi(app, app.Data.nodes(idx).params, classiObj);
                elseif strcmpi(executionPkg, 'cnn_lstm')
                    app.Data.nodes(idx).params = copyCnnLstmStaticParamsFromClassi(app, app.Data.nodes(idx).params, classiObj);
                elseif strcmpi(executionPkg, 'trackastra')
                    app.Data.nodes(idx).params = copyTrackastraStaticParamsFromClassi(app, app.Data.nodes(idx).params, classiObj);
                else
                    updateClassifierLinkProgress(app,linkProgress,NaN, ...
                        'Importing classifier defaults and checking the release...');
                    app.Data.nodes(idx).params = applyClassifierExecutionDefaults(app, ...
                        app.Data.nodes(idx).params, executionPkg, classiObj, 'missing');
                end
                try
                    varName = matlab.lang.makeValidName(['classi_' char(string(classiId))]);
                    assignin('base', varName, classiObj);
                    app.Data.nodes(idx).params.moduleVar = varName;
                catch
                end
                updateClassifierLinkProgress(app,linkProgress,1, ...
                    'Classifier linked. Refreshing pipeline...');
                closeProgressDialog(app,linkProgress);
                linkProgress = [];
                refreshAfterModelChange(app);
            catch ME
                closeProgressDialog(app,linkProgress);
                uialert(app.UIFigure, ME.message, 'Link classifier', 'Icon', 'error');
            end
        end

        function updateClassifierLinkProgress(app,d,fraction,message) %#ok<INUSD>
            try
                if isempty(d) || ~isvalid(d),return;end
                if isfinite(fraction)
                    d.Indeterminate = 'off';
                    d.Value = max(0,min(1,double(fraction)));
                else
                    d.Indeterminate = 'on';
                end
                d.Message = char(string(message));
                drawnow limitrate nocallbacks;
            catch
            end
        end

        function pkg = classifierPackageName(app, classiObj) %#ok<INUSD>
            pkg = '';
            try
                if isprop(classiObj, 'classifierPkg') && ~isempty(classiObj.classifierPkg)
                    pkg = char(string(classiObj.classifierPkg));
                    return;
                end
            catch
            end
            fun = '';
            try
                if isprop(classiObj, 'classifyFun') && ~isempty(classiObj.classifyFun)
                    fun = char(string(classiObj.classifyFun));
                end
            catch
            end
            if contains(fun, '.')
                pkg = extractBefore(fun, '.');
            elseif strcmpi(fun, 'classifyImageLSTMNetFun')
                pkg = 'cnn_lstm';
            elseif contains(lower(fun), 'cellpose')
                pkg = 'cellposesam';
            elseif contains(lower(fun), 'deeplab')
                pkg = 'deeplab_pixel_classification';
            end
        end

        function params = copyCellposeStaticParamsFromClassi(app, params, classiObj)
            params = applyCellposeExecutionDefaults(app, params, classiObj, 'missing');
        end

        function params = copySam31StaticParamsFromClassi(app, params, classiObj)
            params = applySam31ExecutionDefaults(app, params, classiObj, 'missing');
        end

        function params = copyTrackastraStaticParamsFromClassi(app, params, classiObj)
            params = applyTrackastraExecutionDefaults(app, params, classiObj, 'missing');
        end

        function params = copyDeeplabPixelStaticParamsFromClassi(app, params, classiObj)
            params = applyDeeplabPixelExecutionDefaults(app, params, classiObj, 'missing');
        end

        function params = copyCnnLstmStaticParamsFromClassi(app, params, classiObj)
            params = applyCnnLstmExecutionDefaults(app, params, classiObj, 'missing');
        end

        function importClassifierDefaults(app, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                return;
            end
            try
                classiObj = linkedClassifierObject(app, app.Data.nodes(idx));
                if isempty(classiObj) || ~isa(classiObj, 'classi')
                    error('pipeline2:NoLinkedClassifier', 'No valid linked classifier object is available for this module.');
                end
                pkg = classifierPackageName(app, classiObj);
                if isempty(pkg)
                    pkg = char(string(getField(app, app.Data.nodes(idx), 'pkg', '')));
                end
                [spec, hasExecutionSpec] = classifierExecutionSpec(app, pkg, classiObj);
                if ~hasExecutionSpec || ~isstruct(spec.defaults) || isempty(fieldnames(spec.defaults))
                    error('pipeline2:UnsupportedClassifierDefaults', ...
                        'Classifier package "%s" does not expose importable execution defaults.', pkg);
                end

                choice = uiconfirm(app.UIFigure, ...
                    ['Import execution defaults from the linked classifier into this pipeline node?' newline newline ...
                     'Overwrite replaces current pipeline execution parameters. Missing only keeps existing pipeline values.'], ...
                    'Import classifier defaults', ...
                    'Options', {'Overwrite','Missing only','Cancel'}, ...
                    'DefaultOption', 'Missing only', ...
                    'CancelOption', 'Cancel');
                if strcmp(choice, 'Cancel')
                    return;
                end

                if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                    app.Data.nodes(idx).params = struct();
                end
                mode = 'missing';
                if strcmp(choice, 'Overwrite')
                    mode = 'overwrite';
                end
                switch lower(char(string(pkg)))
                    case 'cellposesam'
                        app.Data.nodes(idx).params = applyCellposeExecutionDefaults(app, app.Data.nodes(idx).params, classiObj, mode);
                    case 'sam31'
                        app.Data.nodes(idx).params = applySam31ExecutionDefaults(app, app.Data.nodes(idx).params, classiObj, mode);
                    case 'trackastra'
                        app.Data.nodes(idx).params = applyTrackastraExecutionDefaults(app, app.Data.nodes(idx).params, classiObj, mode);
                    case 'deeplab_pixel_classification'
                        app.Data.nodes(idx).params = applyDeeplabPixelExecutionDefaults(app, app.Data.nodes(idx).params, classiObj, mode);
                    case 'cnn_lstm'
                        app.Data.nodes(idx).params = applyCnnLstmExecutionDefaults(app, app.Data.nodes(idx).params, classiObj, mode);
                    otherwise
                        app.Data.nodes(idx).params = applyClassifierExecutionDefaults(app, ...
                            app.Data.nodes(idx).params, pkg, classiObj, mode);
                end
                refreshAfterModelChange(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Import classifier defaults', 'Icon', 'error');
            end
        end

        function [params, hasExecutionSpec] = applyClassifierExecutionDefaults(app, params, pkg, classiObj, mode)
            if nargin < 5 || isempty(mode)
                mode = 'missing';
            end
            if nargin < 4
                classiObj = [];
            end
            if ~isstruct(params)
                params = struct();
            end
            [spec, hasExecutionSpec] = classifierExecutionSpec(app, pkg, classiObj);
            if ~hasExecutionSpec
                return;
            end
            keys = spec.defaultImportKeys;
            if isempty(keys)
                keys = unique([spec.staticKeys spec.outputKeys], 'stable');
            end
            keys = setdiff(keys, unique([spec.artifactKeys spec.environmentKeys], 'stable'), 'stable');
            overwrite = strcmpi(char(string(mode)), 'overwrite');
            for i = 1:numel(keys)
                key = keys{i};
                if ~overwrite && isfield(params, key) && ~isempty(params.(key))
                    continue;
                end
                if isfield(spec.defaults, key)
                    params.(key) = spec.defaults.(key);
                end
            end
        end

        function [spec, available] = classifierExecutionSpec(app, pkg, classiObj)
            if nargin < 3
                classiObj = [];
            end
            pkg = canonicalModulePackageName(app, 'classifier', pkg);
            available = true;
            switch lower(strtrim(char(string(pkg))))
                case 'cnn_lstm'
                    spec = cnnLstmExecutionSpec(app, classiObj);
                case 'cellposesam'
                    spec = cellposeExecutionSpec(app, classiObj);
                case 'sam31'
                    spec = sam31ExecutionSpec(app, classiObj);
                case 'trackastra'
                    spec = trackastraExecutionSpec(app, classiObj);
                case 'deeplab_pixel_classification'
                    spec = deeplabPixelExecutionSpec(app, classiObj);
                otherwise
                    spec = struct();
                    specFun = [char(string(pkg)) '.executionSpec'];
                    try
                        spec = feval(specFun, classiObj);
                    catch
                        try
                            spec = feval(specFun);
                        catch
                            available = false;
                        end
                    end
            end
            if ~available || ~isstruct(spec) || isempty(spec)
                available = false;
                spec = struct();
            elseif numel(spec) > 1
                spec = spec(1);
            end
            spec = normalizeClassifierExecutionSpec(app, spec);
            if available && strcmpi(char(string(pkg)), 'cellLatentModel') && ...
                    ~isempty(classiObj) && isa(classiObj, 'classi')
                spec.defaults = classifierApplyTrainingExecutionDefaults( ...
                    spec.defaults, classiObj, spec, 'pipeline');
            end
        end

        function params = migrateCellLatentBindings(app, params) %#ok<INUSD>
            if ~isstruct(params), params = struct(); end
            backend = lower(strtrim(char(string(getField(app, params, ...
                'backend', 'legacy')))));
            if ~strcmp(backend, 'causal_composite'), return; end
            hasInstance = isfield(params, 'instanceChannelName') && ...
                ~isempty(strtrim(char(string(params.instanceChannelName))));
            hasTrack = isfield(params, 'trackChannelName') && ...
                ~isempty(strtrim(char(string(params.trackChannelName))));
            if ~hasInstance && hasTrack
                % Old nodes exposed the Cellpose result in the stable-track
                % slot because their stale backend was legacy.  The promoted
                % composite consumes that same symbolic result as frame-local
                % instances and creates stable tracks itself.
                params.instanceChannelName = params.trackChannelName;
            end
            if isfield(params, 'trackChannelName')
                params.trackChannelName = '';
            end
        end

        function nodes = synchronizeCellLatentModelNodes(app, nodes)
            for ii = 1:numel(nodes)
                if ~strcmpi(char(string(getField(app, nodes(ii), 'type', ''))), 'classifier') || ...
                        ~strcmpi(char(string(getField(app, nodes(ii), 'pkg', ''))), 'cellLatentModel')
                    continue;
                end
                try
                    classiObj = linkedClassifierObject(app, nodes(ii));
                    if isempty(classiObj), continue; end
                    nodes(ii).params = applyClassifierExecutionDefaults(app, ...
                        nodes(ii).params, 'cellLatentModel', classiObj, 'overwrite');
                    nodes(ii).params = migrateCellLatentBindings(app, nodes(ii).params);
                catch
                    % Keep the saved node inspectable; ordinary validation
                    % will report an unavailable or invalid linked artifact.
                end
            end
        end

        function spec = normalizeClassifierExecutionSpec(app, spec)
            if ~isstruct(spec) || isempty(spec)
                spec = struct();
            end
            keyFields = {'staticKeys','artifactKeys','environmentKeys','outputKeys','defaultImportKeys'};
            for i = 1:numel(keyFields)
                fieldName = keyFields{i};
                if ~isfield(spec, fieldName)
                    spec.(fieldName) = {};
                else
                    spec.(fieldName) = normalizeClassifierSpecKeys(app, spec.(fieldName));
                end
            end
            if ~isfield(spec, 'defaults') || ~isstruct(spec.defaults) || isempty(spec.defaults)
                spec.defaults = struct();
            elseif numel(spec.defaults) > 1
                spec.defaults = spec.defaults(1);
            end
            metadataFields = {'labels','tips','choices'};
            for i = 1:numel(metadataFields)
                fieldName = metadataFields{i};
                if ~isfield(spec, fieldName) || ~isstruct(spec.(fieldName)) || isempty(spec.(fieldName))
                    spec.(fieldName) = struct();
                elseif numel(spec.(fieldName)) > 1
                    spec.(fieldName) = spec.(fieldName)(1);
                end
            end
        end

        function keys = normalizeClassifierSpecKeys(app, value)
            keys = flattenChoiceList(app, value);
            keys = cellfun(@(x)char(string(x)), keys, 'UniformOutput', false);
            keys = unique(keys(~cellfun(@isempty, keys)), 'stable');
        end

        function [value, found] = classifierSpecMetadataValue(app, spec, section, key) %#ok<INUSD>
            value = [];
            found = false;
            if ~isstruct(spec) || ~isfield(spec, section) || ~isstruct(spec.(section))
                return;
            end
            values = spec.(section);
            names = fieldnames(values);
            idx = find(strcmpi(names, char(string(key))), 1);
            if isempty(idx)
                return;
            end
            value = values.(names{idx});
            found = true;
        end

        function params = applySam31ExecutionDefaults(app, params, classiObj, mode) %#ok<INUSD>
            if nargin < 4 || isempty(mode)
                mode = 'missing';
            end
            if ~isstruct(params)
                params = struct();
            end
            spec = sam31ExecutionSpec(app, classiObj);
            defaults = spec.defaults;
            keys = unique([spec.staticKeys spec.outputKeys], 'stable');
            overwrite = strcmpi(char(string(mode)), 'overwrite');
            for i = 1:numel(keys)
                key = keys{i};
                if ~overwrite && isfield(params, key) && ~isempty(params.(key))
                    continue;
                end
                if isfield(defaults, key)
                    params.(key) = defaults.(key);
                end
            end
            if isfield(params, 'sam31Runner')
                params.sam31Runner = normalizeSam31RunnerForPipeline(app, params.sam31Runner);
            end
            if isfield(params, 'backend')
                params.backend = normalizeSam31BackendForPipeline(app, params.backend);
            end
        end


        function params = applyTrackastraExecutionDefaults(app, params, classiObj, mode) %#ok<INUSD>
            if nargin < 4 || isempty(mode)
                mode = 'missing';
            end
            if ~isstruct(params)
                params = struct();
            end
            spec = trackastraExecutionSpec(app, classiObj);
            defaults = spec.defaults;
            keys = unique([spec.staticKeys spec.outputKeys], 'stable');
            overwrite = strcmpi(char(string(mode)), 'overwrite');
            for i = 1:numel(keys)
                key = keys{i};
                if ~overwrite && isfield(params, key) && ~isempty(params.(key))
                    continue;
                end
                if isfield(defaults, key)
                    params.(key) = defaults.(key);
                end
            end
        end

        function spec = trackastraExecutionSpec(app, classiObj) %#ok<INUSD>
            if nargin < 2
                classiObj = [];
            end
            try
                spec = trackastra.executionSpec(classiObj);
            catch
                spec = struct();
                spec.staticKeys = {'pretrainedModel','trackingMode','device','batchSize','nWorkers', ...
                    'maxDistance','normalizeImages'};
                spec.artifactKeys = {'modelSource','customModelPath','checkpointPath'};
                spec.environmentKeys = {'pythonExecutable'};
                spec.outputKeys = {'outputName'};
                spec.defaultImportKeys = [spec.staticKeys spec.outputKeys];
                spec.defaults = trackastra.utils.defaultExecutionParam();
                spec.labels = struct();
                spec.tips = struct();
                spec.choices = struct('pretrainedModel',{{'general_2d','general_3d','general_2d_w_SAM2_features'}}, ...
                    'trackingMode',{{'greedy','greedy_nodiv','ilp'}}, ...
                    'device',{{'automatic','cuda','cpu','mps'}}, ...
                    'normalizeImages',{{true,false}});
            end
        end

        function spec = sam31ExecutionSpec(app, classiObj) %#ok<INUSD>
            if nargin < 2
                classiObj = [];
            end
            try
                spec = sam31.executionSpec(classiObj);
            catch
                spec = struct();
                spec.staticKeys = {'backend','resolution','maxNumObjects','chunkSize', ...
                    'chunkOverlap','videoScoreThreshold', ...
                    'videoNewDetThreshold','videoAssocIouThreshold', ...
                    'hotstartUnmatchThreshold','sam31Runner', ...
                    'inferInstanceSegmentation','inferCellTracking', ...
                    'inferBudPairing','budPairingSourceKey', ...
                    'budPairingTypicalCellSize','budPairingMaxBirthAreaFraction', ...
                    'budPairingMinParentAreaReferenceFraction', ...
                    'budPairingMaxParentDistanceFraction', ...
                    'budPairingMaxParentCentroidDistanceFraction', ...
                    'budPairingFutureWindow','budPairingMinParentAgeFrames', ...
                    'budPairingMaxFutureDistanceReferenceFraction', ...
                    'budPairingMaxFutureCentroidDistanceReferenceFraction'};
                spec.outputKeys = {};
                spec.defaultImportKeys = spec.staticKeys;
                spec.defaults = struct('backend', 'local', 'resolution', '280', ...
                    'maxNumObjects',40, ...
                    'chunkSize',32, ...
                    'chunkOverlap',4, ...
                    'videoScoreThreshold',0.4, 'videoNewDetThreshold',0.4, ...
                    'videoAssocIouThreshold',0.5, ...
                    'hotstartUnmatchThreshold',3, ...
                    'sam31Runner', 'session', ...
                    'inferInstanceSegmentation', true, ...
                    'inferCellTracking', true, ...
                    'inferBudPairing', true, ...
                    'budPairingSourceKey', '', ...
                    'budPairingTypicalCellSize', 0, ...
                    'budPairingMaxBirthAreaFraction', 1.25, ...
                    'budPairingMinParentAreaReferenceFraction', 0.7, ...
                    'budPairingMaxParentDistanceFraction', 0.5, ...
                    'budPairingMaxParentCentroidDistanceFraction', 1.0, ...
                    'budPairingFutureWindow', 6, ...
                    'budPairingMinParentAgeFrames', 6, ...
                    'budPairingMaxFutureDistanceReferenceFraction', 1.0, ...
                    'budPairingMaxFutureCentroidDistanceReferenceFraction', 1.0);
                spec.labels = struct();
                spec.tips = struct();
                spec.choices = struct('backend', {{'local','wsl'}}, ...
                    'resolution', {{'280','1008'}}, ...
                    'sam31Runner', {{'session','external'}}, ...
                    'inferInstanceSegmentation', {{true,false}}, ...
                    'inferCellTracking', {{true,false}}, ...
                    'inferBudPairing', {{true,false}});
            end
        end

        function backend = normalizeSam31BackendForPipeline(app, backend) %#ok<INUSD>
            backend = lower(strtrim(char(string(backend))));
            if any(strcmp(backend, {'wsl','linux'}))
                backend = 'wsl';
            else
                backend = 'local';
            end
        end

        function runner = normalizeSam31RunnerForPipeline(app, runner)
            runner = lower(sam31ScalarChoice(app, runner));
            runner = strrep(runner, '-', '_');
            runner = strrep(runner, ' ', '_');
            if any(strcmp(runner, {'external','process','subprocess'}))
                runner = 'external';
            else
                runner = 'session';
            end
        end

        function txt = sam31ScalarChoice(app, value) %#ok<INUSD>
            txt = '';
            while iscell(value)
                value = value(~cellfun(@isempty, value));
                if isempty(value)
                    return;
                end
                value = value{end};
            end
            if isstring(value)
                vals = value(:);
                try
                    vals = vals(~ismissing(vals));
                catch
                end
                if ~isempty(vals)
                    txt = char(vals(end));
                end
            elseif ischar(value)
                if ndims(value) > 2
                    value = value(:);
                end
                if size(value, 1) > 1
                    rows = cellstr(value);
                    txt = rows{end};
                else
                    txt = value;
                end
            elseif isnumeric(value) || islogical(value) || iscategorical(value)
                vals = string(value(:));
                if ~isempty(vals)
                    txt = char(vals(end));
                end
            else
                try
                    vals = string(value);
                    vals = vals(:);
                    if ~isempty(vals)
                        txt = char(vals(end));
                    end
                catch
                    txt = '';
                end
            end
            txt = strtrim(txt);
        end

        function params = applyCnnLstmExecutionDefaults(app, params, classiObj, mode) %#ok<INUSD>
            if nargin < 4 || isempty(mode)
                mode = 'missing';
            end
            if ~isstruct(params)
                params = struct();
            end
            spec = cnnLstmExecutionSpec(app, classiObj);
            defaults = spec.defaults;
            keys = unique([spec.staticKeys spec.outputKeys], 'stable');
            overwrite = strcmpi(char(string(mode)), 'overwrite');
            for i = 1:numel(keys)
                key = keys{i};
                if ~overwrite && isfield(params, key) && ~isempty(params.(key))
                    continue;
                end
                if isfield(defaults, key)
                    params.(key) = defaults.(key);
                end
            end
            if isfield(params, 'outputMode')
                params.outputMode = normalizeCnnLstmOutputModeForPipeline(app, params.outputMode);
            end
            if isfield(params, 'executionEnvironment')
                params.executionEnvironment = normalizeExecutionEnvironmentForPipeline(app, params.executionEnvironment);
            end
        end

        function spec = cnnLstmExecutionSpec(app, classiObj) %#ok<INUSD>
            if nargin < 2
                classiObj = [];
            end
            try
                spec = cnn_lstm.executionSpec(classiObj);
            catch
                spec = struct();
                spec.staticKeys = {'outputMode','executionEnvironment'};
                spec.outputKeys = {'outputName','cnnOutputName'};
                spec.defaultImportKeys = spec.staticKeys;
                spec.defaults = struct('outputMode','lstm_only','outputName','div_1', ...
                    'cnnOutputName','cnn_div_1','executionEnvironment','module_default');
                spec.labels = struct();
                spec.tips = struct();
                spec.choices = struct('outputMode', {{'lstm_only','cnn_only','both'}}, ...
                    'executionEnvironment', {{'module_default','cpu','gpu'}});
            end
        end

        function outputMode = normalizeCnnLstmOutputModeForPipeline(app, outputMode) %#ok<INUSD>
            outputMode = lower(strtrim(char(string(outputMode))));
            outputMode = strrep(outputMode, '-', '_');
            outputMode = strrep(outputMode, ' ', '_');
            switch outputMode
                case {'lstm','lstm_only','primary'}
                    outputMode = 'lstm_only';
                case {'cnn','cnn_only'}
                    outputMode = 'cnn_only';
                case {'both','all','lstm_and_cnn','cnn_and_lstm'}
                    outputMode = 'both';
                otherwise
                    if isempty(outputMode)
                        outputMode = 'lstm_only';
                    end
            end
        end

        function env = normalizeExecutionEnvironmentForPipeline(app, env) %#ok<INUSD>
            env = lower(strtrim(char(string(env))));
            env = strrep(env, '-', '_');
            env = strrep(env, ' ', '_');
            switch env
                case {'gpu','multi_gpu','force_gpu'}
                    env = 'gpu';
                case {'cpu','force_cpu'}
                    env = 'cpu';
                otherwise
                    env = 'module_default';
            end
        end

        function params = applyCellposeExecutionDefaults(app, params, classiObj, mode) %#ok<INUSD>
            if nargin < 4 || isempty(mode)
                mode = 'missing';
            end
            if ~isstruct(params)
                params = struct();
            end
            spec = cellposeExecutionSpec(app, classiObj);
            defaults = spec.defaults;
            keys = unique([spec.staticKeys spec.outputKeys], 'stable');
            overwrite = strcmpi(char(string(mode)), 'overwrite');
            for i = 1:numel(keys)
                key = keys{i};
                if ~overwrite && isfield(params, key) && ~isempty(params.(key))
                    continue;
                end
                if isfield(defaults, key)
                    params.(key) = defaults.(key);
                end
            end
            if isfield(params, 'outputType')
                params.outputType = normalizeCellposeOutputTypeForPipeline(app, params.outputType);
            end
        end

        function spec = cellposeExecutionSpec(app, classiObj) %#ok<INUSD>
            if nargin < 2
                classiObj = [];
            end
            try
                spec = cellposesam.executionSpec(classiObj);
            catch
                spec = struct();
                spec.staticKeys = {'outputType','diameter','min_size','flow_threshold','cell_prob_threshold'};
                spec.outputKeys = {'outputName','probabilityOutputName'};
                spec.defaultImportKeys = spec.staticKeys;
                spec.defaults = struct('outputType','segmentation','outputName','cellposeSAM', ...
                    'probabilityOutputName','cellposeSAM_prob','diameter',NaN,'min_size',10, ...
                    'flow_threshold',0.4,'cell_prob_threshold',0);
                spec.labels = struct();
                spec.tips = struct();
                spec.choices = struct('outputType', {{'segmentation','probability','both'}});
            end
        end

        function params = applyDeeplabPixelExecutionDefaults(app, params, classiObj, mode) %#ok<INUSD>
            if nargin < 4 || isempty(mode)
                mode = 'missing';
            end
            if ~isstruct(params)
                params = struct();
            end
            spec = deeplabPixelExecutionSpec(app, classiObj);
            defaults = spec.defaults;
            keys = unique([spec.staticKeys spec.outputKeys], 'stable');
            overwrite = strcmpi(char(string(mode)), 'overwrite');
            for i = 1:numel(keys)
                key = keys{i};
                if ~overwrite && isfield(params, key) && ~isempty(params.(key))
                    continue;
                end
                if isfield(defaults, key)
                    params.(key) = defaults.(key);
                end
            end
            if isfield(params, 'outputType')
                params.outputType = normalizeDeeplabPixelOutputTypeForPipeline(app, params.outputType);
            end
            if isfield(params, 'executionEnvironment')
                params.executionEnvironment = normalizeExecutionEnvironmentForPipeline(app, params.executionEnvironment);
            end
        end

        function spec = deeplabPixelExecutionSpec(app, classiObj) %#ok<INUSD>
            if nargin < 2
                classiObj = [];
            end
            try
                spec = deeplab_pixel_classification.executionSpec(classiObj);
            catch
                spec = struct();
                spec.staticKeys = {'outputType','executionEnvironment'};
                spec.outputKeys = {'outputName','probabilityOutputName'};
                spec.defaultImportKeys = spec.staticKeys;
                spec.defaults = struct('outputType','segmentation','outputName','deeplab_pixels', ...
                    'probabilityOutputName','deeplab_pixels_prob','executionEnvironment','module_default');
                spec.labels = struct();
                spec.tips = struct();
                spec.choices = struct('outputType', {{'segmentation','probability','both'}}, ...
                    'executionEnvironment', {{'module_default','cpu','gpu'}});
            end
        end

        function outputType = normalizeCellposeOutputTypeForPipeline(app, outputType) %#ok<INUSD>
            outputType = lower(strtrim(char(string(outputType))));
            switch outputType
                case {'proba','probabilities','probability_map'}
                    outputType = 'probability';
                case {'postprocessing','seg','mask','masks','semantic','semantic_segmentation'}
                    outputType = 'segmentation';
                case {'both','probability','segmentation'}
                    % already normalized
                otherwise
                    if isempty(outputType)
                        outputType = 'segmentation';
                    end
            end
        end

        function outputType = normalizeDeeplabPixelOutputTypeForPipeline(app, outputType) %#ok<INUSD>
            outputType = lower(strtrim(char(string(outputType))));
            switch outputType
                case {'proba','probabilities','probability_map'}
                    outputType = 'probability';
                case {'postprocessing','seg','mask','masks','semantic','semantic_segmentation'}
                    outputType = 'segmentation';
                case {'both','probability','segmentation'}
                    % already normalized
                otherwise
                    if isempty(outputType)
                        outputType = 'segmentation';
                    end
            end
        end

        function openLinkedClassifier(app, node)
            try
                classiObj = linkedClassifierObject(app, node);
                if isempty(classiObj) || ~isa(classiObj, 'classi')
                    error('pipeline2:NoLinkedClassifier', 'No valid linked classifier object is available for this module.');
                end
                classifierGUI(classiObj);
            catch ME
                uialert(app.UIFigure, ME.message, 'Open linked classifier', 'Icon', 'error');
            end
        end

        function classiObj = linkedClassifierObject(app, node)
            classiObj = [];
            p = getField(app, node, 'params', struct());
            if ~isstruct(p)
                return;
            end
            if ~isfield(p, 'modulePath') || isempty(p.modulePath)
                return;
            end
            modulePath = char(string(p.modulePath));
            moduleId = '';
            if isfield(p, 'moduleId') && ~isempty(p.moduleId)
                moduleId = char(string(p.moduleId));
            end
            if isempty(moduleId)
                [~, moduleId] = fileparts(modulePath);
            end
            snap = localClassifierSnapshotPath(app, modulePath, moduleId);
            if isempty(snap)
                snap = fullfile(modulePath, [moduleId '_classification.mat']);
            end
            if exist(snap, 'file') ~= 2
                error('pipeline2:MissingLinkedClassifierFile', 'Linked classifier file not found: %s', snap);
            end
            [classiObj, msg] = loadClassifierSnapshotStrict(app, snap);
            if isempty(classiObj) || ~isa(classiObj, 'classi')
                if isempty(msg)
                    msg = 'Linked file is not a valid classi object.';
                end
                error('pipeline2:BadLinkedClassifier', '%s', msg);
            end
        end

        function [classiObj, msg] = loadClassifierSnapshotStrict(app, filePath) %#ok<INUSD>
            classiObj = [];
            msg = '';
            if isempty(filePath) || exist(filePath, 'file') ~= 2
                msg = ['Classifier file not found: ' char(string(filePath))];
                return;
            end
            try
                S = load(filePath);
            catch ME
                msg = ME.message;
                return;
            end

            if isfield(S, 'classiObj') && isa(S.classiObj, 'classi')
                classiObj = S.classiObj;
            else
                names = fieldnames(S);
                for ii = 1:numel(names)
                    cand = S.(names{ii});
                    if isa(cand, 'classi')
                        classiObj = cand;
                        break;
                    end
                end
            end

            if isempty(classiObj) || ~isa(classiObj, 'classi')
                msg = 'This file does not contain a classi object.';
                classiObj = [];
                return;
            end
            if numel(classiObj) > 1
                [~, expectedId] = fileparts(filePath);
                expectedId = regexprep(expectedId, '_classification$', '');
                ids = arrayfun(@(x) char(string(x.strid)), classiObj, 'UniformOutput', false);
                match = find(strcmp(ids, expectedId), 1, 'first');
                if isempty(match)
                    match = 1;
                end
                classiObj = classiObj(match);
            end

            try
                [pth, file] = fileparts(filePath);
                file = regexprep(file, '_classification$', '');
                if ispc
                    pth = [pth '\'];
                else
                    pth = [pth '/'];
                end
                classiObj.setPath(pth, file);
            catch
            end

            try
                classiObj.category = classiNormalizeCategory(classiObj.category);
            catch
            end
            try
                if isprop(classiObj, 'run') && isstruct(classiObj.run) && isfield(classiObj.run, 'active')
                    classiObj.run.active = false;
                end
                classiObj.runNormalizePaths();
            catch
            end
            try
                classiObj.syncDatasetFromLegacy();
                classiObj.syncLegacyFromDataset();
            catch
            end
        end

        function clearClassifierArtifactLink(app, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx) || ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                return;
            end
            removeKeys = {'modulePath','moduleId','moduleVar','classes','classifyFun','trainingFun','trainingParam'};
            for i = 1:numel(removeKeys)
                if isfield(app.Data.nodes(idx).params, removeKeys{i})
                    app.Data.nodes(idx).params = rmfield(app.Data.nodes(idx).params, removeKeys{i});
                end
            end
            refreshAfterModelChange(app);
        end

        function buildRoiPatternTab(app, parentTab, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            runtimeData = paramsToTableData(app, node, 'runtime');

            grid = uigridlayout(parentTab, [4 1]);
            grid.RowHeight = {86, 32, 24, '1x'};
            grid.ColumnWidth = {'1x'};
            grid.Padding = [12 10 12 12];
            grid.RowSpacing = 8;

            [statusLines, statusColor] = roiPatternStatus(app, node);
            status = uitextarea(grid, 'Editable', 'off', 'Value', statusLines);
            status.Layout.Row = 1;
            try
                status.FontColor = statusColor;
            catch
            end

            actions = uigridlayout(grid, [1 3]);
            actions.RowHeight = {28};
            actions.ColumnWidth = {150, 160, '1x'};
            actions.Padding = [0 0 0 0];
            actions.ColumnSpacing = 8;
            actions.Layout.Row = 2;

            nodePatternParams = getRoiPatternParamsForDisplay(app, node);
            hasNodePattern = hasUsableRoiPattern(app, nodePatternParams);
            hintText = ternary(app, hasNodePattern, ...
                'Selection applies to this run. Library saves and replacements are explicit.', ...
                'Choose a library pattern or create one on your images.');

            libraryBtn = uibutton(actions, 'push', 'Text', 'Pattern library...', ...
                'Tag','OpenPatternLibrary', ...
                'ButtonPushedFcn', @(~,~)openRoiPatternLibrary(app, nodeId, false));
            libraryBtn.Layout.Row = 1;
            libraryBtn.Layout.Column = 1;
            libraryBtn.Tooltip = 'Choose a thumbnail for this run, save a new preset, or explicitly replace one.';

            editBtn = uibutton(actions, 'push', 'Text', 'Create / test on data...', ...
                'ButtonPushedFcn', @(~,~)openRoiPatternLibrary(app, nodeId, true));
            editBtn.Layout.Row = 1;
            editBtn.Layout.Column = 2;
            editBtn.Enable = ternary(app, hasRoiWorkflowImageContext(app) && ...
                (app.RuntimeModeUnlocked || app.ViewerDefinitionOnly), 'on', 'off');
            if ~isempty(app.ViewerRoiCallback)
                editBtn.Tooltip = 'Draw or test the pattern on the viewer Image without creating project ROIs.';
            else
                editBtn.Tooltip = 'Draw or test a draft in workflow2, then choose whether to use it for this run or save it to the library.';
            end

            hint = uilabel(actions, 'Text', hintText, ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 1;
            hint.Layout.Column = 3;

            runtimeLabel = uilabel(grid, 'Text', 'Runtime parameters');
            runtimeLabel.FontWeight = 'bold';
            runtimeLabel.Layout.Row = 3;

            if isempty(runtimeData)
                msg = uilabel(grid, 'Text', 'Confirm the motif above. Detection uses this run''s channel and reference frame; preview does not create ROIs.', ...
                    'WordWrap','on', ...
                    'FontAngle', 'italic', 'FontColor', [0.35 0.35 0.35]);
                msg.Layout.Row = 4;
            else
                section = buildParamSection(app, grid, runtimeData, node, app.Data.runMode, 'runtime');
                section.Layout.Row = 4;
            end
        end

        function buildRoiTrackedTab(app, parentTab, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            runtimeData = paramsToTableData(app, node, 'runtime');

            grid = uigridlayout(parentTab, [6 1]);
            grid.RowHeight = {64, 32, 24, 190, 24, '1x'};
            grid.ColumnWidth = {'1x'};
            grid.Padding = [12 10 12 12];
            grid.RowSpacing = 8;

            [statusLines, statusColor] = roiTrackedStatus(app, node);
            txt = uitextarea(grid, 'Editable', 'off', 'Value', statusLines);
            txt.Layout.Row = 1;
            try
                txt.FontColor = statusColor;
            catch
            end

            actions = uigridlayout(grid, [1 2]);
            actions.RowHeight = {28};
            actions.ColumnWidth = {120, '1x'};
            actions.Padding = [0 0 0 0];
            actions.ColumnSpacing = 8;
            actions.Layout.Row = 2;

            resetBtn = uibutton(actions, 'push', 'Text', 'Reset defaults', ...
                'ButtonPushedFcn', @(~,~)resetRoiDefinitionParams(app, nodeId));
            resetBtn.Layout.Row = 1;
            resetBtn.Layout.Column = 1;

            hint = uilabel(actions, 'Text', 'Tracked ROIs are generated from the bound mask; no manual ROI drawing step is required.', ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 1;
            hint.Layout.Column = 2;

            extractLabel = uilabel(grid, 'Text', 'ROI crop extraction channels');
            extractLabel.FontWeight = 'bold';
            extractLabel.Layout.Row = 3;

            extractSection = buildRoiTrackedExtractChannelSection(app, grid, node);
            extractSection.Layout.Row = 4;

            runtimeLabel = uilabel(grid, 'Text', 'Runtime parameters');
            runtimeLabel.FontWeight = 'bold';
            runtimeLabel.Layout.Row = 5;

            if isempty(runtimeData)
                msg = uilabel(grid, 'Text', 'No runtime parameter is declared for this tracked ROI module.', ...
                    'FontAngle', 'italic', 'FontColor', [0.35 0.35 0.35]);
                msg.Layout.Row = 6;
            else
                section = buildParamSection(app, grid, runtimeData, node, app.Data.runMode, 'runtime');
                section.Layout.Row = 6;
            end
        end

        function grid = buildRoiTrackedExtractChannelSection(app, parent, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            channels = roiExtractAvailableChannels(app);
            useAllChannels = roiTrackedUsesAllExtractChannels(app, node);
            hasInventory = ~isempty(channels);
            if ~hasInventory
                channels = {'<no channel inventory>'};
                useAllChannels = true;
            end

            grid = uigridlayout(parent, [3 3]);
            grid.RowHeight = {28, 132, 22};
            grid.ColumnWidth = {96, 170, '1x'};
            grid.Padding = [0 0 0 0];
            grid.RowSpacing = 6;
            grid.ColumnSpacing = 8;

            dirLabel = uilabel(grid, 'Text', 'Output crops');
            dirLabel.Layout.Row = 1;
            dirLabel.Layout.Column = 1;

            resLabel = uilabel(grid, 'Text', 'channels');
            resLabel.Layout.Row = 1;
            resLabel.Layout.Column = 2;

            mode = uidropdown(grid);
            mode.Items = {'All available channels', 'Select channels manually'};
            mode.ItemsData = {'all', 'manual'};
            mode.Value = ternary(app, useAllChannels, 'all', 'manual');
            mode.Tooltip = ['All available channels leaves extractChannels unset, so roiTracked extracts every channel found for the source ROI. ' ...
                'Manual mode stores a concrete extractChannels list.'];
            mode.ValueChangedFcn = @(src,~)roiTrackedExtractModeChanged(app, nodeId, src.Value);
            mode.Layout.Row = 1;
            mode.Layout.Column = 3;

            table = uitable(grid);
            table.ColumnName = {'Use','Channel'};
            table.ColumnEditable = [true false];
            table.ColumnWidth = {52, 'auto'};
            table.RowName = {};
            table.Data = roiExtractChannelTableData(app, node, channels, useAllChannels);
            table.Enable = ternary(app, useAllChannels, 'off', 'on');
            table.Tooltip = 'Select one or more channels to extract into the newly generated tracked ROI H5 files.';
            table.CellEditCallback = @(src,event)roiTrackedExtractChannelTableEdited(app, nodeId, src, event);
            table.Layout.Row = 2;
            table.Layout.Column = [2 3];

            hint = uilabel(grid, 'Text', ternary(app, useAllChannels, ...
                ternary(app, hasInventory, ...
                    'Default: every available source ROI channel is extracted, including the mask channel used to generate tracked ROIs.', ...
                    'No concrete channel inventory is available yet; all source ROI channels will be resolved at run time.'), ...
                'Manual mode: checked channels are stored in extractChannels for the tracked ROI extraction step.'), ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 3;
            hint.Layout.Column = [2 3];
        end

        function tf = roiTrackedUsesAllExtractChannels(app, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            value = [];
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            if isstruct(runtimeParams) && isfield(runtimeParams, 'extractChannels')
                value = runtimeParams.extractChannels;
            else
                p = getField(app, node, 'params', struct());
                if isstruct(p) && isfield(p, 'extractChannels')
                    value = p.extractChannels;
                end
            end
            txt = strtrim(choiceScalarText(app, value));
            tf = isempty(txt) || isSymbolicStoredBinding(app, txt) || startsWith(txt, '<') || any(strcmpi(txt, {'all','<all>','*',':'}));
        end

        function roiTrackedExtractModeChanged(app, nodeId, modeValue)
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            if strcmpi(char(string(modeValue)), 'all')
                if isfield(app.Data.nodes(idx).params, 'extractChannels')
                    app.Data.nodes(idx).params = rmfield(app.Data.nodes(idx).params, 'extractChannels');
                end
                clearRuntimeNodeParam(app, nodeId, 'extractChannels');
            else
                channels = roiExtractAvailableChannels(app);
                if isempty(channels)
                    if isfield(app.Data.nodes(idx).params, 'extractChannels')
                        app.Data.nodes(idx).params = rmfield(app.Data.nodes(idx).params, 'extractChannels');
                    end
                    clearRuntimeNodeParam(app, nodeId, 'extractChannels');
                else
                    app.Data.nodes(idx).params.extractChannels = channels;
                    runtimeParams.extractChannels = channels;
                    setRuntimeNodeParams(app, nodeId, runtimeParams);
                end
            end
            markPipelineDirty(app, true);
            refreshModuleTabs(app);
            refreshValidationReport(app, false);
        end

        function roiTrackedExtractChannelTableEdited(app, nodeId, table, event)
            if isempty(event.Indices) || event.Indices(2) ~= 1
                return;
            end
            data = table.Data;
            if isempty(data)
                return;
            end
            selected = {};
            for i = 1:size(data,1)
                try
                    if logical(data{i,1})
                        selected{end+1} = char(string(data{i,2})); %#ok<AGROW>
                    end
                catch
                end
            end
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            app.Data.nodes(idx).params.extractChannels = selected;
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            runtimeParams.extractChannels = selected;
            setRuntimeNodeParams(app, nodeId, runtimeParams);
            markPipelineDirty(app, true);
            refreshValidationReport(app, false);
        end

        function buildGenericRoiDefinitionTab(app, parentTab, node)
            runtimeData = paramsToTableData(app, node, 'runtime');
            grid = uigridlayout(parentTab, [3 1]);
            grid.RowHeight = {32, 24, '1x'};
            grid.Padding = [12 10 12 12];
            grid.RowSpacing = 8;

            nodeId = char(string(getField(app, node, 'id', '')));
            editBtn = uibutton(grid, 'push', 'Text', 'Open ROI editor...', ...
                'ButtonPushedFcn', @(~,~)openRoiDefinitionEditor(app, nodeId));
            editBtn.Layout.Row = 1;

            runtimeLabel = uilabel(grid, 'Text', 'Runtime parameters');
            runtimeLabel.FontWeight = 'bold';
            runtimeLabel.Layout.Row = 2;

            if isempty(runtimeData)
                msg = uilabel(grid, 'Text', 'No runtime parameter is declared for this ROI module.', ...
                    'FontAngle', 'italic', 'FontColor', [0.35 0.35 0.35]);
                msg.Layout.Row = 3;
            else
                section = buildParamSection(app, grid, runtimeData, node, app.Data.runMode, 'runtime');
                section.Layout.Row = 3;
            end
        end

        function openRoiDefinitionEditor(app, nodeId)
            if ~isempty(app.ViewerRoiCallback), app.requestViewerRois(nodeId); return; end
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            node = app.Data.nodes(idx);
            if any(strcmpi(node.type,{'roiPattern','roiIdentify'}))
                openRoiPatternLibrary(app,nodeId,true);
                return;
            end
            [editorProject, ok, msg] = resolveRoiEditorProject(app, node);
            if ~ok
                uialert(app.UIFigure, msg, 'ROI editor', 'Icon', 'warning');
                return;
            end

            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            ctx = buildRoiDefinitionEditorContext(app, node);
            ctx.shallow = editorProject;
            ctx.shallowObj = editorProject;
            try
                switch nodeType
                    case 'roiidentify'
                        ctx = roiIdentify.ui(ctx);
                    case 'roipattern'
                        ctx = roiPattern.ui(ctx);
                    case 'roigrid'
                        ctx = roiGrid.ui(ctx);
                    case 'roimanual'
                        ctx = roiManual.ui(ctx);
                    case 'roitracked'
                        ctx = roiTracked.ui(ctx);
                    otherwise
                        error('pipeline2:UnsupportedRoiEditor', 'No dedicated ROI editor is registered for node type "%s".', nodeType);
                end
                if isfield(ctx, 'cancelled') && logical(ctx.cancelled)
                    return;
                end
                applyRoiDefinitionEditorResult(app, nodeId, nodeType, ctx);
                refreshAfterModelChange(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'ROI editor', 'Icon', 'error');
            end
        end

        function accepted = openRoiPatternLibrary(app, nodeId, startEdit)
            if ~isempty(app.ViewerRoiCallback) && startEdit
                accepted = false; app.requestViewerRois(nodeId); return;
            end
            accepted = false;
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx), return; end
            draft = getRoiPatternParamsForDisplay(app, app.Data.nodes(idx));
            try
                if ~app.RuntimeModeUnlocked && ~app.ViewerDefinitionOnly
                    dlg = roiPatternLibraryDialog(roiPatternLibraryFolder(app),draft,nodeId,false,true);
                    dialogCleanup = onCleanup(@()delete(dlg)); %#ok<NASGU>
                    uiwait(dlg.UIFigure);
                    return;
                end
                if startEdit
                    [draft, edited] = editRoiPatternDraft(app, app.Data.nodes(idx), draft);
                    if ~edited, return; end
                end
                while true
                    dlg = roiPatternLibraryDialog(roiPatternLibraryFolder(app), draft, nodeId, hasRoiWorkflowImageContext(app));
                    dialogCleanup = onCleanup(@()delete(dlg));
                    uiwait(dlg.UIFigure);
                    action = dlg.Action; result = dlg.Result;
                    clear dialogCleanup;
                    switch action
                        case 'use'
                            result = pipelineInvalidateRoiPatternPreview(result);
                            if app.ViewerDefinitionOnly
                                applyRoiDefinitionEditorResult(app,nodeId,'roipattern',struct('params',result));
                                markPipelineDirty(app,true);
                            else
                                setRuntimeNodeParams(app, nodeId, result);
                            end
                            rebuildModuleTabForNode(app, idx);
                            accepted = true;
                            return;
                        case 'edit'
                            if ~isempty(app.ViewerRoiCallback)
                                app.requestViewerRois(nodeId,result); return;
                            end
                            [editedParams, edited] = editRoiPatternDraft(app, app.Data.nodes(idx), result);
                            if edited, draft = editedParams; end
                        otherwise
                            return;
                    end
                end
            catch ME
                uialert(app.UIFigure,ME.message,'Pattern library','Icon','error');
            end
        end

        function folder = roiPatternLibraryFolder(app)
            folder = app.CurrentPipelinePath;
            if app.CurrentPipelineIsRunSnapshot && ~isempty(app.CurrentRun)
                folder = getNestedRunField(app, app.CurrentRun, {'pipelineRef','path'}, folder);
            end
            folder = char(string(folder));
            if endsWith(folder,'.json','IgnoreCase',true), folder = fileparts(folder); end
        end

        function [result, accepted] = editRoiPatternDraft(app, node, draft)
            result = draft; accepted = false;
            if ~isempty(app.ViewerRoiCallback), app.requestViewerRois(node.id,draft); return; end
            [editorProject, ok, msg] = resolveRoiEditorProject(app, node);
            if ~ok
                uialert(app.UIFigure,msg,'Create / test pattern','Icon','warning');
                return;
            end
            wf = workflow2(editorProject,'FocusModule','roiPattern','Params',draft);
            editorCleanup = onCleanup(@()delete(wf)); %#ok<NASGU>
            wf.UIFigure.Name = 'Create / test pattern on data';
            wf.ProceedButton.Text = 'Keep draft';
            uiwait(wf.UIFigure);
            if isvalid(wf) && ~wf.Cancelled
                result = wf.Result;
                if isfield(draft,'pattern') && isfield(result,'pattern') && ...
                        ~isequaln(draft.pattern,result.pattern) && isfield(result,'patternPreset')
                    result = rmfield(result,'patternPreset');
                end
                accepted = true;
            end
        end

        function accepted = confirmRoiPatternsForRun(app)
            accepted = true;
            selected = selectedRunNodeIds(app);
            for i = 1:numel(app.Data.nodes)
                node = app.Data.nodes(i);
                if ~ismember(node.id, selected) || ~any(strcmpi(node.type,{'roiPattern','roiIdentify'}))
                    continue;
                end
                if ~openRoiPatternLibrary(app,node.id,false)
                    accepted = false;
                    return;
                end
            end
        end

        function openWorkflowRoiEditor(app, nodeId)
            if ~isempty(app.ViewerRoiCallback), app.requestViewerRois(nodeId); return; end
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            node = app.Data.nodes(idx);
            [editorProject, ok, msg] = resolveRoiEditorProject(app, node);
            if ~ok
                uialert(app.UIFigure, msg, 'ROI workflow', 'Icon', 'warning');
                return;
            end

            if any(strcmpi(node.type,{'roiPattern','roiIdentify'}))
                openRoiPatternLibrary(app,nodeId,true);
                return;
            end
            focus = lower(char(string(getField(app, node, 'type', ''))));
            switch focus
                case 'roiidentify'
                    focus = 'roiPattern';
                case 'roipattern'
                    focus = 'roiPattern';
                case 'roigrid'
                    focus = 'roiGrid';
                case 'roimanual'
                    focus = 'roiManual';
                case 'roitracked'
                    focus = 'roiTracked';
            end

            try
                params = getField(app, node, 'params', struct());
                if any(strcmpi(focus, {'roiPattern','roipattern'}))
                    params = getRoiPatternParamsForDisplay(app, node);
                end
                wf = workflow2(editorProject, 'FocusModule', focus, 'Params', params);
                try
                    if ~isempty(wf) && isvalid(wf.UIFigure)
                        uiwait(wf.UIFigure);
                    end
                catch
                end
                if ~isempty(wf) && isvalid(wf) && ~wf.Cancelled
                    result = wf.Result;
                    params = getField(app, app.Data.nodes(idx), 'params', struct());
                    if ~isstruct(params)
                        params = struct();
                    end
                    if isstruct(result)
                        app.Data.nodes(idx).params = mergeStructOverride(app, params, result);
                        syncRoiPatternBindingFromParams(app, nodeId, result);
                    end
                else
                    try
                        delete(wf);
                    catch
                    end
                    return;
                end
                try
                    delete(wf);
                catch
                end
                refreshAfterModelChange(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'ROI workflow', 'Icon', 'error');
            end
        end

        function [editorProject, ok, msg] = resolveRoiEditorProject(app, node)
            editorProject = [];
            ok = false;
            msg = '';

            if nargin < 2
                node = struct();
            end

            mode = getRuntimeValue(app, 'inputSourceMode');
            if isempty(mode)
                mode = 'existing_rois';
            end

            requiredFovCount = roiEditorRequiredFovCount(app, node);
            if (~strcmpi(char(string(mode)), 'raw_dataloader') || requiredFovCount > 0) && ...
                    currentProjectCanServeRoiEditor(app, requiredFovCount)
                editorProject = app.CurrentProject;
                ok = true;
                return;
            end

            if ~strcmpi(char(string(mode)), 'raw_dataloader')
                if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                    editorProject = app.CurrentProject;
                    ok = true;
                    return;
                end

                msg = ['A shallow project must be selected in the Run tab before opening workflow, ' ...
                    'unless Input mode is set to Parse raw images into project.'];
                return;
            end

            rawDataPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
            if isempty(rawDataPath) || ~(exist(rawDataPath, 'dir') == 7 || exist(rawDataPath, 'file') == 2)
                msg = 'A valid raw data path must be selected before opening the ROI workflow.';
                return;
            end

            try
                editorProject = buildWorkflowProjectFromRawData(app, rawDataPath);
                if requiredFovCount > 0 && numel(editorProject.fov) < requiredFovCount && ...
                        currentProjectCanServeRoiEditor(app, requiredFovCount)
                    editorProject = app.CurrentProject;
                end
                ok = ~isempty(editorProject) && isa(editorProject, 'shallow') && ...
                        ~isempty(editorProject.fov) && ~isempty(editorProject.fov(1).srcpath);
                if ~ok
                    msg = 'Raw data were parsed, but no displayable FOV was created.';
                elseif requiredFovCount > 0 && numel(editorProject.fov) < requiredFovCount
                    msg = sprintf(['The ROI editor project only exposes %d FOV(s), but this manual ROI ' ...
                        'definition references FOV %d. Relink the project raw data or choose a raw/project ' ...
                        'path that covers all selected FOVs.'], numel(editorProject.fov), requiredFovCount);
                    ok = false;
                end
            catch ME
                msg = ['Could not create a temporary ROI workflow project from raw data: ' ME.message];
            end
        end

        function tf = currentProjectCanServeRoiEditor(app, requiredFovCount)
            tf = false;
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            try
                if isempty(app.CurrentProject.fov)
                    return;
                end
                if nargin >= 2 && ~isempty(requiredFovCount) && requiredFovCount > 0 && ...
                        numel(app.CurrentProject.fov) < requiredFovCount
                    return;
                end
                tf = projectHasFovImageSources(app, app.CurrentProject);
            catch
                tf = false;
            end
        end

        function n = roiEditorRequiredFovCount(app, node) %#ok<INUSD>
            n = 0;
            params = getField(app, node, 'params', struct());
            if ~isstruct(params) || isempty(params)
                return;
            end
            fovIdx = [];
            if isfield(params, 'manualRois') && isstruct(params.manualRois) && ~isempty(params.manualRois)
                for i = 1:numel(params.manualRois)
                    try
                        if isfield(params.manualRois(i), 'fovIndex') && ~isempty(params.manualRois(i).fovIndex)
                            fovIdx(end+1) = round(double(params.manualRois(i).fovIndex(1))); %#ok<AGROW>
                        end
                    catch
                    end
                end
            end
            if isempty(fovIdx) && isfield(params, 'fovIndex') && ~isempty(params.fovIndex)
                try
                    fovIdx = reshape(round(double(params.fovIndex)), 1, []);
                catch
                    fovIdx = [];
                end
            end
            fovIdx = fovIdx(isfinite(fovIdx) & fovIdx >= 1);
            if ~isempty(fovIdx)
                n = max(fovIdx);
            end
        end

        function editorProject = buildWorkflowProjectFromRawData(app, rawDataPath)
            rawDataPath = char(string(rawDataPath));
            if ~isempty(app.WorkflowRawProject) && isa(app.WorkflowRawProject, 'shallow') && ...
                    strcmp(char(string(app.WorkflowRawProjectPath)), rawDataPath)
                editorProject = app.WorkflowRawProject;
                return;
            end

            d = [];
            try
                d = uiprogressdlg(app.UIFigure, 'Title', 'ROI workflow', ...
                    'Message', 'Parsing raw data for ROI editor...', 'Indeterminate', 'on', 'Cancelable', 'off');
                ddProgressGuard4 = onCleanup(@()detecdiv_close_progress(d)); %#ok<NASGU>
                drawnow nocallbacks;
            catch
            end

            cleanupObj = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
            updateRuntimeProgress(app, d, 'Creating temporary shallow project...');

            editorProject = shallow();
            [basePath, baseName, baseExt] = fileparts(rawDataPath);
            if isempty(basePath)
                basePath = pwd;
            end
            editorProject.io.path = basePath;
            editorProject.io.file = ['workflow2_raw_' matlab.lang.makeValidName([baseName baseExt])];

            updateRuntimeProgress(app, d, 'Parsing raw FOVs, channels and frames...');
            editorProject.addData(rawDataPath);

            updateRuntimeProgress(app, d, 'Preparing image context...');
            app.WorkflowRawProject = editorProject;
            app.WorkflowRawProjectPath = rawDataPath;
        end

        function ctx = buildRoiDefinitionEditorContext(app, node)
            ctx = struct();
            if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                ctx.shallow = app.CurrentProject;
                ctx.shallowObj = app.CurrentProject;
            end
            ctx.allowGUI = true;
            ctx.interactive = true;
            ctx.params = getField(app, node, 'params', struct());
            ctx.run = struct();
            ctx.run.nodeParams = buildRunNodeParams(app);
            ctx.run.selectedNodes = selectedRunNodeIds(app);
            ctx.sel = struct();
            ctx.sel.fovs = parseRuntimeFovSelection(app, getRuntimeValue(app, 'fovs'));
            ctx.sel.frames = parseIndexSelection(app, getRuntimeValue(app, 'frames'));
            ctx.sel.rois = parseLooseSelection(app, getRuntimeValue(app, 'rois'));
            ctx.fovIndex = ctx.sel.fovs;
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            switch nodeType
                case 'roiidentify'
                    ctx.roiIdentify = ctx.params;
                case 'roipattern'
                    ctx.roiPattern = ctx.params;
                case 'roigrid'
                    ctx.roiGrid = mergeStructDefaults(app, ctx.params, roiGrid.setparam(struct()));
                case 'roimanual'
                    ctx.roiManual = mergeStructDefaults(app, ctx.params, roiManual.setparam(struct()));
                case 'roitracked'
                    ctx.roiTracked = ctx.params;
            end
        end

        function applyRoiDefinitionEditorResult(app, nodeId, nodeType, ctx)
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            params = getField(app, app.Data.nodes(idx), 'params', struct());
            if ~isstruct(params)
                params = struct();
            end

            result = struct();
            switch lower(char(string(nodeType)))
                case 'roiidentify'
                    if isfield(ctx, 'roiIdentify') && isstruct(ctx.roiIdentify)
                        result = ctx.roiIdentify;
                    elseif isfield(ctx, 'roiPattern') && isstruct(ctx.roiPattern)
                        result = ctx.roiPattern;
                    end
                case 'roipattern'
                    if isfield(ctx, 'roiPattern') && isstruct(ctx.roiPattern)
                        result = ctx.roiPattern;
                    elseif isfield(ctx, 'roiIdentify') && isstruct(ctx.roiIdentify)
                        result = ctx.roiIdentify;
                    end
                case 'roigrid'
                    if isfield(ctx, 'roiGrid') && isstruct(ctx.roiGrid)
                        result = ctx.roiGrid;
                    end
                case 'roimanual'
                    if isfield(ctx, 'roiManual') && isstruct(ctx.roiManual)
                        result = ctx.roiManual;
                    end
                case 'roitracked'
                    if isfield(ctx, 'roiTracked') && isstruct(ctx.roiTracked)
                        result = ctx.roiTracked;
                    end
            end
            if isempty(fieldnames(result)) && isfield(ctx, 'params') && isstruct(ctx.params)
                result = ctx.params;
            end
            if ~isempty(fieldnames(result))
                app.Data.nodes(idx).params = mergeStructOverride(app, params, result);
                if ~app.ViewerDefinitionOnly && app.RuntimeModeUnlocked
                    runtime = getRuntimeNodeParams(app,nodeId);
                    for key = fieldnames(result)'
                        if strcmp(pipelineParamScope(app.Data.nodes(idx),key{1}),'run')
                            runtime.(key{1}) = result.(key{1});
                        end
                    end
                    setRuntimeNodeParams(app,nodeId,runtime);
                end
            end
            if ~app.ViewerDefinitionOnly && isfield(ctx, 'shallow') && isa(ctx.shallow, 'shallow')
                app.CurrentProject = ctx.shallow;
            end
        end

        function reloadRoiDefinitionFromProject(app, nodeId)
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            profile = roiDefinitionProfileFromProject(app, app.Data.nodes(idx));
            if isempty(profile)
                uialert(app.UIFigure, 'No saved project profile was found for this ROI module.', ...
                    'Reload ROI profile', 'Icon', 'warning');
                return;
            end
            params = getField(app, app.Data.nodes(idx), 'params', struct());
            if ~isstruct(params)
                params = struct();
            end
            app.Data.nodes(idx).params = mergeStructOverride(app, params, profile);
            refreshAfterModelChange(app);
        end

        function tf = hasRoiWorkflowImageContext(app)
            tf = false;
            if ~isempty(app.ViewerRoiCallback), tf = true; return; end
            rawDataPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
            if ~isempty(rawDataPath) && (exist(rawDataPath, 'dir') == 7 || exist(rawDataPath, 'file') == 2)
                tf = true;
                return;
            end
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            try
                if isempty(app.CurrentProject.fov)
                    return;
                end
                fovObj = app.CurrentProject.fov(1);
                if isprop(fovObj, 'srcpath') && ~isempty(fovObj.srcpath)
                    tf = true;
                elseif isprop(fovObj, 'src') && ~isempty(fovObj.src)
                    tf = true;
                end
            catch
                tf = false;
            end
        end

        function params = getRoiPatternParamsForDisplay(app, node)
            params = getField(app, node, 'params', struct());
            % Review the artifact in the context of the current run. Runtime
            % bindings (notably channel) must override the static/source
            % metadata stored with the pattern.
            nodeId = char(string(getField(app, node, 'id', '')));
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            if isstruct(runtimeParams) && ~isempty(fieldnames(runtimeParams))
                params = mergeStructOverride(app, params, runtimeParams);
            end
        end

        function syncRoiPatternBindingFromParams(app, nodeId, params)
            if ~isstruct(params)
                return;
            end
            pat = struct();
            if isfield(params, 'pattern') && isstruct(params.pattern) && ~isempty(params.pattern)
                pat = params.pattern(1);
            elseif isfield(params, 'patternList') && isstruct(params.patternList) && ~isempty(params.patternList)
                pat = params.patternList(1);
            end
            channelValue = roiPatternFirstNonEmpty(app, params, pat, {'channel','patternSourceChannel','sourceChannel'});
            if isempty(channelValue)
                channelIndex = roiPatternFirstNonEmpty(app, params, pat, {'channelIndex'});
                channelValue = channelNameFromRuntimeIndex(app, channelIndex);
            end
            channelValue = strtrim(choiceScalarText(app, channelValue));
            if isempty(channelValue) || strcmp(channelValue, '<unresolved>')
                return;
            end
            setNodeInputBindingValue(app, nodeId, 'channel', channelValue);
        end

        function channelName = channelNameFromRuntimeIndex(app, channelIndex)
            channelName = '';
            if isempty(channelIndex)
                return;
            end
            try
                idx = round(double(channelIndex(1)));
                if idx < 1
                    return;
                end
                if isfield(app.RuntimeParseInfo, 'channels') && numel(app.RuntimeParseInfo.channels) >= idx
                    channelName = char(string(app.RuntimeParseInfo.channels(idx)));
                end
            catch
                channelName = '';
            end
        end

        function setNodeInputBindingValue(app, nodeId, param, value)
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            app.Data.nodes(idx).params.(char(string(param))) = char(string(value));
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            runtimeParams.(char(string(param))) = char(string(value));
            setRuntimeNodeParams(app, nodeId, runtimeParams);
        end

        function viewRoiPatternArtifact(app, nodeId)
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            params = getRoiPatternParamsForDisplay(app, app.Data.nodes(idx));
            if ~hasUsableRoiPattern(app, params)
                uialert(app.UIFigure, 'No pattern is stored in this module. Please generate pattern first.', ...
                    'View pattern', 'Icon', 'warning');
                return;
            end
            pat = struct();
            if isfield(params, 'pattern') && isstruct(params.pattern) && ~isempty(params.pattern)
                pat = params.pattern(1);
            elseif isfield(params, 'patternList') && isstruct(params.patternList) && ~isempty(params.patternList)
                pat = params.patternList(1);
            end
            img = [];
            try
                if isfield(pat, 'image') && ~isempty(pat.image)
                    img = pat.image;
                elseif isfield(params, 'patternImage') && ~isempty(params.patternImage)
                    img = params.patternImage;
                end
            catch
                img = [];
            end
            description = roiPatternDescription(app, params);
            if isempty(img)
                uialert(app.UIFigure, strjoin([{'Pattern metadata is available, but no image patch is stored.'} description], newline), ...
                    'View pattern', 'Icon', 'info');
                return;
            end
            try
                fig = figure('Name', ['ROI pattern - ' char(string(nodeId))], 'NumberTitle', 'off');
                ax = axes(fig);
                if isnumeric(img) && ndims(img) == 3 && size(img, 3) >= 3
                    image(ax, img);
                else
                    imagesc(ax, img);
                    colormap(ax, gray);
                end
                axis(ax, 'image');
                axis(ax, 'off');
                title(ax, strjoin(description, ' | '), 'Interpreter', 'none');
            catch ME
                uialert(app.UIFigure, ME.message, 'View pattern', 'Icon', 'error');
            end
        end

        function resetRoiDefinitionParams(app, nodeId)
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            nodeType = lower(char(string(getField(app, app.Data.nodes(idx), 'type', ''))));
            switch nodeType
                case 'roiidentify'
                    defaults = roiIdentify.setparam(struct());
                case 'roipattern'
                    defaults = roiPattern.setparam(struct());
                case 'roigrid'
                    defaults = roiGrid.setparam(struct());
                case 'roimanual'
                    defaults = roiManual.setparam(struct());
                case 'roitracked'
                    defaults = roiTracked.setparam(struct());
                otherwise
                    defaults = struct();
            end
            app.Data.nodes(idx).params = defaults;
            removeRuntimeNodeParams(app, nodeId);
            refreshAfterModelChange(app);
        end

        function profile = roiDefinitionProfileFromProject(app, node)
            profile = [];
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            try
                rp = app.CurrentProject.runProfiles;
                if ~isstruct(rp) || ~isfield(rp, 'dataloading') || ~isstruct(rp.dataloading)
                    return;
                end
                dl = rp.dataloading;
                switch lower(char(string(getField(app, node, 'type', ''))))
                    case {'roipattern','roiidentify'}
                        keys = {'roiPattern','roiIdentify'};
                    case 'roigrid'
                        keys = {'roiGrid'};
                    case 'roimanual'
                        keys = {'roiManual'};
                    case 'roitracked'
                        keys = {'roiTracked'};
                    otherwise
                        keys = {};
                end
                for i = 1:numel(keys)
                    if isfield(dl, keys{i}) && isstruct(dl.(keys{i})) && ~isempty(dl.(keys{i}))
                        profile = dl.(keys{i});
                        return;
                    end
                end
            catch
                profile = [];
            end
        end

        function clearRoiPatternNodeArtifact(app, nodeId)
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx) || ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                return;
            end
            removeKeys = {'pattern','patternImage','patternRect','patternList','activePatternIndex', ...
                'patternSourceFOV','patternSourceFrame','patternSourceChannel','crop','fovCrop'};
            for i = 1:numel(removeKeys)
                if isfield(app.Data.nodes(idx).params, removeKeys{i})
                    app.Data.nodes(idx).params = rmfield(app.Data.nodes(idx).params, removeKeys{i});
                end
            end
            refreshAfterModelChange(app);
        end

        function [lines, color] = roiGridStatus(app, node)
            params = getField(app, node, 'params', struct());
            profile = roiDefinitionProfileFromProject(app, node);
            if ~isstruct(params) || isempty(fieldnames(params))
                params = struct();
            end
            params = mergeStructDefaults(app, params, roiGrid.setparam(struct()));
            n = 1;
            mode = 'fullframe';
            try
                n = max(1, round(double(params.gridCount)));
                mode = char(string(params.mode));
            catch
            end
            lines = { ...
                sprintf('Grid artifact: %s, %d ROI(s) per FOV', mode, n), ...
                'This module has no image patch artifact; it stores a deterministic full-frame/grid recipe.'};
            if ~isempty(profile)
                lines{end+1} = 'A saved project profile is available and is used automatically unless this node overrides it.';
                color = [0.10 0.42 0.20];
            else
                color = [0.20 0.20 0.20];
            end
        end

        function [lines, color] = roiManualStatus(app, node)
            params = getField(app, node, 'params', struct());
            profile = roiDefinitionProfileFromProject(app, node);
            nRect = manualRectCount(app, params);
            nProfileRect = manualRectCount(app, profile);
            if nRect > 0
                dimLines = manualRectDimensionLines(app, params);
                lines = { ...
                    sprintf('Manual ROI artifact: %d rectangle(s) stored in this pipeline node.', nRect), ...
                    'Use the dedicated editor to draw or revise these ROIs with image context.'};
                if ~isempty(dimLines)
                    lines = [lines dimLines]; %#ok<AGROW>
                end
                color = [0.10 0.42 0.20];
            elseif nProfileRect > 0
                dimLines = manualRectDimensionLines(app, profile);
                lines = { ...
                    'Manual ROI source: project profile.', ...
                    sprintf('%d rectangle(s) are saved in the project ROI profile and will be used at run time.', nProfileRect), ...
                    'This pipeline node does not store its own manual ROI override.'};
                if ~isempty(dimLines)
                    lines = [lines dimLines]; %#ok<AGROW>
                end
                color = [0.62 0.32 0.08];
            elseif ~isempty(profile)
                lines = { ...
                    'Manual ROI profile found, but no compact rectangle metadata is available.', ...
                    'Open the dedicated editor to inspect or complete the manual ROI definition.'};
                color = [0.62 0.32 0.08];
            else
                lines = { ...
                    'Manual ROI artifact: not defined in this node.', ...
                    'Manual ROI drawing requires image context; open the dedicated editor before running this module.'};
                color = [0.70 0.10 0.10];
            end
        end

        function n = manualRectCount(app, params) %#ok<INUSD>
            n = 0;
            if ~isstruct(params) || isempty(params)
                return;
            end
            keys = {'manualRois','rectangles','roiRects','rois','positions','manualRects'};
            for i = 1:numel(keys)
                k = keys{i};
                if ~isfield(params, k) || isempty(params.(k))
                    continue;
                end
                v = params.(k);
                if isnumeric(v) && size(v, 2) >= 4
                    n = size(v, 1);
                    return;
                elseif isstruct(v)
                    n = numel(v);
                    return;
                elseif iscell(v)
                    n = numel(v);
                    return;
                end
            end
        end

        function lines = manualRectDimensionLines(app, params)
            rects = manualRectArray(app, params);
            lines = {};
            if isempty(rects)
                return;
            end
            nShow = min(size(rects, 1), 4);
            for i = 1:nShow
                r = rects(i, 1:4);
                lines{end+1} = sprintf('ROI %d: x=%g, y=%g, w=%g, h=%g px', i, r(1), r(2), r(3), r(4)); %#ok<AGROW>
            end
            if size(rects, 1) > nShow
                lines{end+1} = sprintf('... %d more ROI rectangle(s).', size(rects, 1) - nShow);
            end
        end

        function rects = manualRectArray(app, params) %#ok<INUSD>
            rects = zeros(0,4);
            if ~isstruct(params) || isempty(params)
                return;
            end
            keys = {'manualRois','rectangles','roiRects','rois','positions','manualRects'};
            for i = 1:numel(keys)
                k = keys{i};
                if ~isfield(params, k) || isempty(params.(k))
                    continue;
                end
                v = params.(k);
                if isnumeric(v) && size(v, 2) >= 4
                    rects = double(v(:,1:4));
                    return;
                elseif isstruct(v)
                    out = zeros(0,4);
                    for j = 1:numel(v)
                        r = [];
                        if isfield(v, 'rect') && ~isempty(v(j).rect)
                            r = v(j).rect;
                        elseif isfield(v, 'position') && ~isempty(v(j).position)
                            r = v(j).position;
                        elseif isfield(v, 'value') && ~isempty(v(j).value)
                            r = v(j).value;
                        end
                        if isnumeric(r) && numel(r) >= 4
                            out(end+1,:) = double(r(1:4)); %#ok<AGROW>
                        end
                    end
                    rects = out;
                    return;
                elseif iscell(v)
                    out = zeros(0,4);
                    for j = 1:numel(v)
                        r = v{j};
                        if isnumeric(r) && numel(r) >= 4
                            out(end+1,:) = double(r(1:4)); %#ok<AGROW>
                        end
                    end
                    rects = out;
                    return;
                end
            end
        end

        function [lines, color] = roiTrackedStatus(app, node)
            params = getField(app, node, 'params', struct());
            profile = roiDefinitionProfileFromProject(app, node);
            if ~isstruct(params) || isempty(fieldnames(params))
                params = struct();
            end
            params = mergeStructDefaults(app, params, roiTracked.setparam(struct()));

            source = choiceScalarText(app, getField(app, params, 'channel', ''));
            if isempty(strtrim(source))
                source = '<mask channel unresolved>';
            end
            extractState = 'extract ROI crops';
            try
                if ~logical(params.extract)
                    extractState = 'do not extract ROI crops';
                end
            catch
            end
            marginText = valueToDisplay(app, getField(app, params, 'margin', 0));
            lines = { ...
                ['Tracked ROI recipe: mask channel = ' source], ...
                ['Margin: ' marginText ' px | ' extractState], ...
                'Creates tracked child ROIs after the full-frame/source ROI and extracts their H5 crops.'};
            if ~isempty(profile)
                lines{end+1} = 'A saved project profile is available and is used automatically unless this node overrides it.';
                color = [0.10 0.42 0.20];
            elseif strcmp(source, '<mask channel unresolved>')
                color = [0.70 0.10 0.10];
            else
                color = [0.20 0.20 0.20];
            end
        end

        function [lines, color] = roiPatternStatus(app, node)
            params = getRoiPatternParamsForDisplay(app, node);
            profile = roiDefinitionProfileFromProject(app, node);
            if hasUsableRoiPattern(app, params)
                lines = {'Pattern selected for this run.'};
                if isfield(params,'patternPreset') && isfield(params.patternPreset,'name')
                    lines{end+1} = sprintf('Library: %s | revision %d',params.patternPreset.name,params.patternPreset.revision);
                else
                    lines{end+1} = 'Current / legacy motif. Save it to the library to give it a reusable name.';
                end
                if roiPatternHasSourceContext(app, params)
                    lines{end+1} = 'Pattern was generated from existing raw data and stores its source context.';
                end
                lines = [lines roiPatternDescription(app, params)];
                color = [0.10 0.42 0.20];
            else
                lines = { ...
                    'Choose a pattern for this run.', ...
                    'Open Pattern library, or Create / test on data.'};
                if hasUsableRoiPattern(app, profile)
                    lines{end+1} = 'A legacy project ROI profile exists, but it is not stored in this module.';
                end
                color = [0.70 0.10 0.10];
            end
        end

        function tf = roiPatternHasSourceContext(app, params)
            tf = false;
            if ~isstruct(params) || isempty(params)
                return;
            end
            pat = struct();
            if isfield(params, 'pattern') && isstruct(params.pattern) && ~isempty(params.pattern)
                pat = params.pattern(1);
            elseif isfield(params, 'patternList') && isstruct(params.patternList) && ~isempty(params.patternList)
                pat = params.patternList(1);
            end
            fovValue = roiPatternFirstNonEmpty(app, params, pat, {'patternSourceFOV','sourceFOV','sourceFov','fovIndex'});
            frameValue = roiPatternFirstNonEmpty(app, params, pat, {'patternSourceFrame','sourceFrame','frame','referenceFrame'});
            channelValue = roiPatternFirstNonEmpty(app, params, pat, {'patternSourceChannel','sourceChannel','channel','channelIndex'});
            tf = ~isempty(fovValue) || ~isempty(frameValue) || ~isempty(channelValue);
        end

        function tf = hasUsableRoiPattern(app, params) %#ok<INUSD>
            tf = false;
            if ~isstruct(params) || isempty(params)
                return;
            end
            try
                if isfield(params, 'pattern') && isstruct(params.pattern) && ~isempty(params.pattern)
                    tf = true;
                    return;
                end
                if isfield(params, 'patternList') && isstruct(params.patternList) && ~isempty(params.patternList)
                    tf = true;
                    return;
                end
                if isfield(params, 'patternRect') && isnumeric(params.patternRect) && numel(params.patternRect) >= 4
                    tf = true;
                end
            catch
                tf = false;
            end
        end

        function lines = roiPatternDescription(app, params)
            lines = {};
            if ~isstruct(params) || isempty(params)
                return;
            end
            pat = struct();
            if isfield(params, 'pattern') && isstruct(params.pattern) && ~isempty(params.pattern)
                pat = params.pattern(1);
            elseif isfield(params, 'patternList') && isstruct(params.patternList) && ~isempty(params.patternList)
                pat = params.patternList(1);
            end
            rect = roiPatternFirstNonEmpty(app, params, pat, {'patternRect','rect','position','crop'});
            if isnumeric(rect) && numel(rect) >= 4
                lines{end+1} = ['Rect: ' mat2str(double(rect(1:4)), 4)]; %#ok<AGROW>
            end
            fovValue = roiPatternFirstNonEmpty(app, params, pat, {'patternSourceFOV','sourceFOV','sourceFov','fovIndex'});
            frameValue = roiPatternFirstNonEmpty(app, params, pat, {'patternSourceFrame','sourceFrame','frame','referenceFrame'});
            channelValue = roiPatternFirstNonEmpty(app, params, pat, {'patternSourceChannel','sourceChannel','channel','channelIndex'});
            sourceBits = {};
            if ~isempty(fovValue), sourceBits{end+1} = ['FOV ' valueToDisplay(app, fovValue)]; end %#ok<AGROW>
            if ~isempty(frameValue), sourceBits{end+1} = ['frame ' valueToDisplay(app, frameValue)]; end %#ok<AGROW>
            if ~isempty(channelValue), sourceBits{end+1} = ['channel ' valueToDisplay(app, channelValue)]; end %#ok<AGROW>
            if ~isempty(sourceBits)
                lines{end+1} = ['Source: ' strjoin(sourceBits, ', ')]; %#ok<AGROW>
            end
            if isempty(lines)
                lines = {'Pattern metadata found, but no compact source description is available.'};
            end
        end

        function value = roiPatternFirstNonEmpty(app, params, pat, keys) %#ok<INUSD>
            value = [];
            for i = 1:numel(keys)
                k = keys{i};
                if isstruct(params) && isfield(params, k) && ~isempty(params.(k))
                    value = params.(k);
                    return;
                end
                if isstruct(pat) && isfield(pat, k) && ~isempty(pat.(k))
                    value = pat.(k);
                    return;
                end
            end
        end

        function out = mergeStructOverride(app, base, override) %#ok<INUSD>
            if ~isstruct(base)
                base = struct();
            end
            out = base;
            if ~isstruct(override) || isempty(override)
                return;
            end
            fn = fieldnames(override);
            for i = 1:numel(fn)
                out.(fn{i}) = override.(fn{i});
            end
        end

        function buildRoiGridTab(app, parentTab, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            runtimeData = paramsToTableData(app, node, 'runtime');
            grid = uigridlayout(parentTab, [4 1]);
            grid.RowHeight = {74, 32, 24, '1x'};
            grid.ColumnWidth = {'1x'};
            grid.Padding = [12 10 12 12];
            grid.RowSpacing = 8;

            [statusLines, statusColor] = roiGridStatus(app, node);
            status = uitextarea(grid, 'Editable', 'off', 'Value', statusLines);
            status.Layout.Row = 1;
            try
                status.FontColor = statusColor;
            catch
            end

            actions = uigridlayout(grid, [1 3]);
            actions.RowHeight = {28};
            actions.ColumnWidth = {150, 120, '1x'};
            actions.Padding = [0 0 0 0];
            actions.ColumnSpacing = 8;
            actions.Layout.Row = 2;

            editBtn = uibutton(actions, 'push', 'Text', 'Open ROI workflow...', ...
                'ButtonPushedFcn', @(~,~)openWorkflowRoiEditor(app, nodeId));
            if ~isempty(app.ViewerRoiCallback), editBtn.Text = 'Define ROIs...'; end
            editBtn.Layout.Row = 1;
            editBtn.Layout.Column = 1;

            resetBtn = uibutton(actions, 'push', 'Text', 'Reset defaults', ...
                'ButtonPushedFcn', @(~,~)resetRoiDefinitionParams(app, nodeId));
            resetBtn.Layout.Row = 1;
            resetBtn.Layout.Column = 2;

            hint = uilabel(actions, 'Text', 'Grid/full-frame geometry is edited in the dedicated dialog; pipeline2 only stores the resulting parameters.', ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 1;
            hint.Layout.Column = 3;

            runtimeLabel = uilabel(grid, 'Text', 'Runtime parameters');
            runtimeLabel.FontWeight = 'bold';
            runtimeLabel.Layout.Row = 3;

            if isempty(runtimeData)
                msg = uilabel(grid, 'Text', 'No runtime parameter is declared for this ROI grid module.', ...
                    'FontAngle', 'italic', 'FontColor', [0.35 0.35 0.35]);
                msg.Layout.Row = 4;
            else
                section = buildParamSection(app, grid, runtimeData, node, app.Data.runMode, 'runtime');
                section.Layout.Row = 4;
            end
        end

        function roiGridCountChanged(app, src, valueOverride)
            if nargin < 3 || isempty(valueOverride)
                n = max(1, round(double(src.Value)));
            else
                n = max(1, round(double(valueOverride)));
            end
            src.Value = n;

            nodeIdx = roiGridNodeIndexForControl(app, src);
            if isempty(nodeIdx)
                return;
            end

            tiling = computeRoiGridTiling(app, n);
            app.Data.nodes(nodeIdx).params.gridCount = tiling.count;
            app.Data.nodes(nodeIdx).params.tiling = rmfield(tiling, 'active');
            if n <= 1
                app.Data.nodes(nodeIdx).params.mode = 'fullframe';
            else
                app.Data.nodes(nodeIdx).params.mode = 'grid';
            end

            ax = roiGridPreviewAxesForControl(app, src);
            if ~isempty(ax)
                drawRoiGridPreview(app, ax, tiling);
            end
            refreshValidationReport(app, false);
        end

        function idx = roiGridNodeIndexForControl(app, src)
            idx = [];
            nodeId = '';
            try
                if isstruct(src.UserData) && isfield(src.UserData, 'nodeId')
                    nodeId = char(string(src.UserData.nodeId));
                end
            catch
            end
            if ~isempty(nodeId)
                ids = {app.Data.nodes.id};
                idx = find(strcmp(ids, nodeId), 1);
            end
            if isempty(idx) && ~isnan(app.SelectedNodeIndex) && app.SelectedNodeIndex >= 1 && app.SelectedNodeIndex <= numel(app.Data.nodes)
                idx = app.SelectedNodeIndex;
            end
        end

        function ax = roiGridPreviewAxesForControl(app, src)
            ax = gobjects(0);
            try
                if isstruct(src.UserData) && isfield(src.UserData, 'previewAxes') && isvalid(src.UserData.previewAxes)
                    ax = src.UserData.previewAxes;
                    return;
                end
            catch
            end
            try
                tab = ancestor(src, 'matlab.ui.container.Tab');
                found = findall(tab, 'Type', 'axes');
                if ~isempty(found)
                    ax = found(1);
                end
            catch
                found = findall(app.TabGroup.SelectedTab, 'Type', 'axes');
                if ~isempty(found)
                    ax = found(1);
                end
            end
        end

        function n = resolveGridCount(app, node)
            n = 1;
            p = getField(app, node, 'params', struct());
            if isstruct(p) && isfield(p, 'gridCount') && ~isempty(p.gridCount)
                n = max(1, round(double(p.gridCount)));
            end
        end

        function tiling = computeRoiGridTiling(app, count) %#ok<INUSD>
            count = max(1, round(double(count)));
            if count <= 1
                rows = 1;
                cols = 1;
            else
                cols = ceil(sqrt(count));
                rows = ceil(count / cols);
            end
            active = false(rows, cols);
            active(1:count) = true;
            tiling = struct('count', count, 'rows', rows, 'cols', cols, 'active', active);
        end

        function drawRoiGridPreview(app, ax, tiling) %#ok<INUSD>
            if isnumeric(tiling)
                tiling = computeRoiGridTiling(app, tiling);
            end
            count = tiling.count;
            cla(ax);
            hold(ax, 'on');
            axis(ax, [0 1 0 1]);
            axis(ax, 'ij');
            ax.XTick = [];
            ax.YTick = [];

            rectangle(ax, 'Position', [0.06 0.08 0.88 0.82], 'FaceColor', [0.96 0.97 0.98], ...
                'EdgeColor', [0.18 0.24 0.30], 'LineWidth', 1.4);

            if count <= 1
                text(ax, 0.5, 0.49, '1 full-frame ROI', 'HorizontalAlignment', 'center', ...
                    'FontWeight', 'bold', 'Color', [0.18 0.24 0.30]);
            else
                nRows = tiling.rows;
                nCols = tiling.cols;
                w = 0.88 / nCols;
                h = 0.82 / nRows;
                for r = 1:nRows
                    for c = 1:nCols
                        if ~tiling.active(r, c)
                            continue;
                        end
                        x = 0.06 + (c - 1) * w;
                        y = 0.08 + (r - 1) * h;
                        rectangle(ax, 'Position', [x y w h], 'FaceColor', [0.75 0.86 0.95], ...
                            'EdgeColor', [0.12 0.38 0.62], 'LineWidth', 1.0);
                    end
                end
                text(ax, 0.5, 0.96, sprintf('%d tiled ROIs', count), 'HorizontalAlignment', 'center', ...
                    'FontWeight', 'bold', 'Color', [0.18 0.24 0.30]);
            end
            hold(ax, 'off');
            drawnow limitrate nocallbacks;
        end

        function buildRoiManualTab(app, parentTab, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            runtimeData = paramsToTableData(app, node, 'runtime');
            grid = uigridlayout(parentTab, [4 1]);
            grid.RowHeight = {74, 32, 24, '1x'};
            grid.ColumnWidth = {'1x'};
            grid.Padding = [12 10 12 12];
            grid.RowSpacing = 8;

            [statusLines, statusColor] = roiManualStatus(app, node);
            status = uitextarea(grid, 'Editable', 'off', 'Value', statusLines);
            status.Layout.Row = 1;
            try
                status.FontColor = statusColor;
            catch
            end

            actions = uigridlayout(grid, [1 3]);
            actions.RowHeight = {28};
            actions.ColumnWidth = {160, 120, '1x'};
            actions.Padding = [0 0 0 0];
            actions.ColumnSpacing = 8;
            actions.Layout.Row = 2;

            editBtn = uibutton(actions, 'push', 'Text', 'Open drawing workflow...', ...
                'ButtonPushedFcn', @(~,~)openWorkflowRoiEditor(app, nodeId));
            if ~isempty(app.ViewerRoiCallback), editBtn.Text = 'Define ROIs...'; end
            editBtn.Layout.Row = 1;
            editBtn.Layout.Column = 1;

            resetBtn = uibutton(actions, 'push', 'Text', 'Reset defaults', ...
                'ButtonPushedFcn', @(~,~)resetRoiDefinitionParams(app, nodeId));
            resetBtn.Layout.Row = 1;
            resetBtn.Layout.Column = 2;

            hint = uilabel(actions, 'Text', 'Manual ROI drawing needs image context and is handled in workflow focused on ROI manual mode.', ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 1;
            hint.Layout.Column = 3;

            runtimeLabel = uilabel(grid, 'Text', 'Runtime parameters');
            runtimeLabel.FontWeight = 'bold';
            runtimeLabel.Layout.Row = 3;

            if isempty(runtimeData)
                msg = uilabel(grid, 'Text', 'No runtime parameter is declared for this manual ROI module.', ...
                    'FontAngle', 'italic', 'FontColor', [0.35 0.35 0.35]);
                msg.Layout.Row = 4;
            else
                section = buildParamSection(app, grid, runtimeData, node, app.Data.runMode, 'runtime');
                section.Layout.Row = 4;
            end
        end

        function params = getRuntimeNodeParams(app, nodeId)
            params = struct();
            key = runtimeNodeKey(app, nodeId);
            if isfield(app.RuntimeNodeParams, key) && isstruct(app.RuntimeNodeParams.(key))
                params = app.RuntimeNodeParams.(key);
            end
        end

        function setRuntimeNodeParams(app, nodeId, params)
            key = runtimeNodeKey(app, nodeId);
            app.RuntimeNodeParams.(key) = params;
            markRunDirty(app, true);
        end

        function clearRuntimeNodeParam(app, nodeId, param)
            key = runtimeNodeKey(app, nodeId);
            param = char(string(param));
            try
                if isfield(app.RuntimeNodeParams, key) && isstruct(app.RuntimeNodeParams.(key)) && ...
                        isfield(app.RuntimeNodeParams.(key), param)
                    app.RuntimeNodeParams.(key) = rmfield(app.RuntimeNodeParams.(key), param);
                end
            catch
            end
        end

        function renameRuntimeNodeParams(app, oldId, newId)
            oldKey = runtimeNodeKey(app, oldId);
            newKey = runtimeNodeKey(app, newId);
            if isfield(app.RuntimeNodeParams, oldKey)
                app.RuntimeNodeParams.(newKey) = app.RuntimeNodeParams.(oldKey);
                app.RuntimeNodeParams = rmfield(app.RuntimeNodeParams, oldKey);
            end
        end

        function renameSymbolicBindingReferences(app, oldId, newId)
            for i = 1:numel(app.Data.nodes)
                if isfield(app.Data.nodes(i), 'params') && isstruct(app.Data.nodes(i).params)
                    app.Data.nodes(i).params = renameSymbolicBindingStruct(app, app.Data.nodes(i).params, oldId, newId);
                end
            end

            keys = fieldnames(app.RuntimeNodeParams);
            for i = 1:numel(keys)
                key = keys{i};
                if isstruct(app.RuntimeNodeParams.(key))
                    app.RuntimeNodeParams.(key) = renameSymbolicBindingStruct(app, app.RuntimeNodeParams.(key), oldId, newId);
                end
            end
        end

        function params = renameSymbolicBindingStruct(app, params, oldId, newId)
            names = fieldnames(params);
            for i = 1:numel(names)
                key = names{i};
                params.(key) = renameSymbolicBindingValue(app, params.(key), oldId, newId);
            end
        end

        function value = renameSymbolicBindingValue(app, value, oldId, newId) %#ok<INUSD>
            if ischar(value) || (isstring(value) && isscalar(value))
                txt = char(string(value));
                if startsWith(strtrim(txt), '@resource:')
                    parts = strsplit(txt, ':');
                    if numel(parts) >= 3 && strcmp(parts{3}, oldId)
                        parts{3} = newId;
                        value = strjoin(parts, ':');
                    end
                elseif startsWith(strtrim(txt), '@')
                    pattern = ['output\s+from\s+' regexptranslate('escape', oldId) '(\s*/|\s*$)'];
                    replacement = ['output from ' newId '$1'];
                    value = regexprep(txt, pattern, replacement);
                end
            elseif iscell(value)
                for j = 1:numel(value)
                    value{j} = renameSymbolicBindingValue(app, value{j}, oldId, newId);
                end
            end
        end

        function removeRuntimeNodeParams(app, nodeId)
            key = runtimeNodeKey(app, nodeId);
            if isfield(app.RuntimeNodeParams, key)
                app.RuntimeNodeParams = rmfield(app.RuntimeNodeParams, key);
            end
        end

        function key = runtimeNodeKey(app, nodeId) %#ok<INUSD>
            key = matlab.lang.makeValidName(['node_' char(string(nodeId))]);
        end

        function out = mergeStructDefaults(app, out, defaults) %#ok<INUSD>
            if ~isstruct(out)
                out = struct();
            end
            fn = fieldnames(defaults);
            for i = 1:numel(fn)
                k = fn{i};
                if ~isfield(out, k) || isempty(out.(k))
                    out.(k) = defaults.(k);
                end
            end
        end

        function rects = getRoiManualRectangles(app, nodeId)
            params = getRuntimeNodeParams(app, nodeId);
            rects = zeros(0,4);
            if isfield(params, 'rectangles') && isnumeric(params.rectangles) && size(params.rectangles, 2) == 4
                rects = clipRoiManualRectangles(app, double(params.rectangles));
            end
        end

        function setRoiManualRectangles(app, nodeId, rects)
            params = getRuntimeNodeParams(app, nodeId);
            params = mergeStructDefaults(app, params, roiManual.setparam(struct()));
            params.rectangles = clipRoiManualRectangles(app, rects);
            setRuntimeNodeParams(app, nodeId, params);
        end

        function rects = clipRoiManualRectangles(app, rects) %#ok<INUSD>
            if isempty(rects)
                rects = zeros(0,4);
                return;
            end
            rects = double(rects(:,1:4));
            rects(~isfinite(rects)) = 0;
            rects(:,3:4) = max(rects(:,3:4), 0.02);
            rects(:,1:2) = max(rects(:,1:2), 0);
            rects(:,3) = min(rects(:,3), 1);
            rects(:,4) = min(rects(:,4), 1);
            rects(:,1) = min(rects(:,1), 1 - rects(:,3));
            rects(:,2) = min(rects(:,2), 1 - rects(:,4));
            rects(:,1:2) = max(rects(:,1:2), 0);
        end

        function data = roiManualRectanglesToTable(app, rects) %#ok<INUSD>
            data = cell(size(rects, 1), 5);
            for i = 1:size(rects, 1)
                data{i,1} = i;
                for j = 1:4
                    data{i,j+1} = rects(i,j);
                end
            end
        end

        function rects = roiManualTableToRectangles(app, data) %#ok<INUSD>
            rects = zeros(0,4);
            if isempty(data)
                return;
            end
            rects = zeros(size(data,1), 4);
            for i = 1:size(data,1)
                for j = 1:4
                    v = data{i,j+1};
                    if ischar(v) || isstring(v)
                        v = str2double(char(string(v)));
                    end
                    if isempty(v) || ~isnumeric(v) || ~isscalar(v) || ~isfinite(v)
                        v = 0;
                    end
                    rects(i,j) = double(v);
                end
            end
            rects = clipRoiManualRectangles(app, rects);
        end

        function drawRoiManualPreview(app, ax, nodeId, table, rects)
            clearRoiManualPreviewHandles(app);
            cla(ax);
            hold(ax, 'on');
            axis(ax, [0 1 0 1]);
            axis(ax, 'ij');
            ax.XTick = [];
            ax.YTick = [];
            rectangle(ax, 'Position', [0.03 0.05 0.94 0.88], 'FaceColor', [0.96 0.97 0.98], ...
                'EdgeColor', [0.18 0.24 0.30], 'LineWidth', 1.4);
            text(ax, 0.5, 0.965, 'normalized preview - data previewer comes next', ...
                'HorizontalAlignment', 'center', 'FontAngle', 'italic', 'Color', [0.35 0.35 0.35]);
            if isempty(rects)
                text(ax, 0.5, 0.49, 'No manual ROI rectangle', 'HorizontalAlignment', 'center', ...
                    'FontWeight', 'bold', 'Color', [0.35 0.35 0.35]);
            end
            for i = 1:size(rects, 1)
                color = [0.12 0.55 0.22];
                if isequal(i, app.RoiManualSelectedRectangle)
                    color = [1.00 0.65 0.00];
                end
                try
                    h = drawrectangle(ax, 'Position', rects(i,:), 'Color', color, 'LineWidth', 1.6);
                    h.UserData = struct('nodeId', nodeId, 'index', i);
                    try, h.Label = sprintf('R%d', i); catch, end
                    app.RoiManualPreviewHandles{end+1} = h; %#ok<AGROW>
                    app.RoiManualPreviewListeners{end+1} = addlistener(h, 'ROIMoved', @(src,~)roiManualRectangleMoved(app, src, table, ax)); %#ok<AGROW>
                catch
                    rectangle(ax, 'Position', rects(i,:), 'EdgeColor', color, 'LineWidth', 1.6);
                    text(ax, rects(i,1), max(0, rects(i,2)-0.02), sprintf('R%d', i), ...
                        'Color', color, 'FontWeight', 'bold');
                end
            end
            hold(ax, 'off');
            drawnow limitrate nocallbacks;
        end

        function clearRoiManualPreviewHandles(app)
            for i = 1:numel(app.RoiManualPreviewListeners)
                try, delete(app.RoiManualPreviewListeners{i}); catch, end
            end
            app.RoiManualPreviewListeners = {};
            for i = 1:numel(app.RoiManualPreviewHandles)
                try
                    if isvalid(app.RoiManualPreviewHandles{i})
                        delete(app.RoiManualPreviewHandles{i});
                    end
                catch
                end
            end
            app.RoiManualPreviewHandles = {};
        end

        function roiManualRuntimeOptionChanged(app, nodeId, key, value)
            params = getRuntimeNodeParams(app, nodeId);
            params = mergeStructDefaults(app, params, roiManual.setparam(struct()));
            params.(char(string(key))) = logical(value);
            setRuntimeNodeParams(app, nodeId, params);
            refreshValidationReport(app, false);
        end

        function roiManualAddRectangle(app, nodeId, table, ax)
            rects = getRoiManualRectangles(app, nodeId);
            n = size(rects, 1);
            offset = 0.04 * mod(n, 5);
            newRect = [0.18 + offset, 0.18 + offset, 0.28, 0.22];
            rects(end+1,:) = clipRoiManualRectangles(app, newRect); %#ok<AGROW>
            app.RoiManualSelectedRectangle = size(rects, 1);
            setRoiManualRectangles(app, nodeId, rects);
            table.Data = roiManualRectanglesToTable(app, rects);
            drawRoiManualPreview(app, ax, nodeId, table, rects);
            refreshValidationReport(app, false);
        end

        function roiManualClearSelectedRectangle(app, nodeId, table, ax)
            rects = getRoiManualRectangles(app, nodeId);
            idx = app.RoiManualSelectedRectangle;
            if isempty(idx) || isnan(idx) || idx < 1 || idx > size(rects, 1)
                try
                    sel = table.Selection;
                    if ~isempty(sel)
                        idx = sel(1,1);
                    end
                catch
                end
            end
            if isempty(idx) || isnan(idx) || idx < 1 || idx > size(rects, 1)
                return;
            end
            rects(idx,:) = [];
            app.RoiManualSelectedRectangle = NaN;
            setRoiManualRectangles(app, nodeId, rects);
            table.Data = roiManualRectanglesToTable(app, rects);
            drawRoiManualPreview(app, ax, nodeId, table, rects);
            refreshValidationReport(app, false);
        end

        function roiManualClearAllRectangles(app, nodeId, table, ax)
            app.RoiManualSelectedRectangle = NaN;
            rects = zeros(0,4);
            setRoiManualRectangles(app, nodeId, rects);
            table.Data = roiManualRectanglesToTable(app, rects);
            drawRoiManualPreview(app, ax, nodeId, table, rects);
            refreshValidationReport(app, false);
        end

        function roiManualRectangleTableEdited(app, nodeId, table, event)
            rects = roiManualTableToRectangles(app, table.Data);
            setRoiManualRectangles(app, nodeId, rects);
            table.Data = roiManualRectanglesToTable(app, rects);
            app.RoiManualSelectedRectangle = event.Indices(1);
            try
                parentTab = ancestor(table, 'matlab.ui.container.Tab');
                ax = findall(parentTab, 'Type', 'axes');
            catch
                ax = findall(app.TabGroup.SelectedTab, 'Type', 'axes');
            end
            if ~isempty(ax)
                drawRoiManualPreview(app, ax(1), nodeId, table, rects);
            end
            refreshValidationReport(app, false);
        end

        function roiManualRectangleSelectionChanged(app, event)
            if isempty(event.Selection)
                app.RoiManualSelectedRectangle = NaN;
            else
                app.RoiManualSelectedRectangle = event.Selection(1,1);
            end
        end

        function roiManualRectangleMoved(app, src, table, ax)
            try
                ud = src.UserData;
                nodeId = char(string(ud.nodeId));
                idx = double(ud.index);
            catch
                return;
            end
            rects = getRoiManualRectangles(app, nodeId);
            if idx < 1 || idx > size(rects, 1)
                return;
            end
            rects(idx,:) = clipRoiManualRectangles(app, double(src.Position));
            app.RoiManualSelectedRectangle = idx;
            setRoiManualRectangles(app, nodeId, rects);
            if isvalid(table)
                table.Data = roiManualRectanglesToTable(app, rects);
            end
            drawRoiManualPreview(app, ax, nodeId, table, rects);
            refreshValidationReport(app, false);
        end

        function grid = buildBindingSection(app, parent, data, node, editable)
            n = max(1, size(data, 1));
            grid = uigridlayout(parent, [n 3]);
            grid.Scrollable='on';
            rowHeights = repmat({28}, 1, n);
            for r = 1:size(data, 1)
                if strcmpi(char(string(data{r,1})), 'Input') && isDataSeriesVariableBindingParamForUi(app, data{r,3})
                    rowHeights{r} = numericBindingHeight(app,node,data{r,3},data{r,4});
                end
            end
            grid.RowHeight = rowHeights;
            if strcmpi(node.pkg,'lstm_timeseries')
                grid.ColumnWidth = {70, 105, '1x', 70};
            else
                grid.ColumnWidth = {70, 105, '1x'};
            end
            grid.Padding = [0 0 0 0];
            grid.RowSpacing = 6;
            grid.ColumnSpacing = 8;

            for i = 1:size(data, 1)
                direction = char(string(data{i,1}));
                resourceLabel = char(string(data{i,2}));
                param = char(string(data{i,3}));
                value = data{i,4};
                choices = data{i,5};
                tooltip = char(string(data{i,6}));

                dirLabel = uilabel(grid, 'Text', direction);
                dirLabel.Layout.Row = i;
                dirLabel.Layout.Column = 1;
                dirLabel.Tooltip = tooltip;

                resLabel = uilabel(grid, 'Text', resourceLabel);
                resLabel.Layout.Row = i;
                resLabel.Layout.Column = 2;
                resLabel.Tooltip = tooltip;

                ctrl = createBindingControl(app, grid, node, param, value, choices, direction, editable);
                ctrl.Layout.Row = i;
                ctrl.Layout.Column = 3;
                if strcmpi(node.pkg,'lstm_timeseries')&&~isempty(regexp(param,'^feature\d+$','once'))
                    remove=uibutton(grid,'Text','Remove','Tag',['RemoveInput_' param], ...
                        'Enable',ternary(app,editable&&node.params.featureCount>1,'on','off'), ...
                        'ButtonPushedFcn',@(~,~)changeNumericInputList(app,node,param));
                    remove.Layout.Row=i;remove.Layout.Column=4;
                end
                if isempty(ctrl.Tooltip)
                    ctrl.Tooltip = tooltip;
                elseif ~isempty(tooltip)
                    ctrl.Tooltip = [char(string(ctrl.Tooltip)) newline tooltip];
                end
            end
        end

        function h = bindingSectionPreferredHeight(app, data)
            if isempty(data)
                h = 34;
                return;
            end
            rowHeights = zeros(1, size(data, 1));
            for r = 1:size(data, 1)
                if strcmpi(char(string(data{r,1})), 'Input') && isDataSeriesVariableBindingParamForUi(app, data{r,3})
                    rowHeights(r) = 81;
                else
                    rowHeights(r) = 28;
                end
            end
            h = sum(rowHeights) + max(0, numel(rowHeights) - 1) * 6 + 2;
        end

        function grid = buildRoiExtractBindingSection(app, parent, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            channels = roiExtractAvailableChannels(app);
            useSymbolic = roiExtractUsesSymbolicSource(app, node);
            hasInventory = ~isempty(channels);
            if ~hasInventory
                channels = {'<no channel inventory>'};
                useSymbolic = true;
            end

            grid = uigridlayout(parent, [3 4]);
            grid.RowHeight = {28, 142, 22};
            grid.ColumnWidth = {96, 170, '1x', 116};
            grid.Padding = [0 0 0 0];
            grid.RowSpacing = 6;
            grid.ColumnSpacing = 8;

            dirLabel = uilabel(grid, 'Text', 'Input');
            dirLabel.Layout.Row = 1;
            dirLabel.Layout.Column = 1;

            resLabel = uilabel(grid, 'Text', 'channel/source');
            resLabel.Layout.Row = 1;
            resLabel.Layout.Column = 2;

            mode = uidropdown(grid);
            mode.Items = {'<source output>', 'Select channels manually'};
            mode.ItemsData = {'symbolic', 'manual'};
            mode.Value = ternary(app, useSymbolic, 'symbolic', 'manual');
            mode.Tooltip = 'Symbolic mode uses all source channels provided by the upstream dataloader/source. Manual mode selects concrete channels below.';
            mode.ValueChangedFcn = @(src,~)roiExtractSourceModeChanged(app, nodeId, src.Value);
            mode.Layout.Row = 1;
            mode.Layout.Column = 3;

            channelData = roiExtractChannelTableData(app, node, channels, useSymbolic);
            table = uitable(grid);
            table.ColumnName = {'Use','Channel'};
            table.ColumnEditable = [true false];
            table.ColumnWidth = {52, 'auto'};
            table.RowName = {};
            table.Data = channelData;
            table.Enable = ternary(app, useSymbolic, 'off', 'on');
            table.Tooltip = 'Select one or more source channels to extract into ROI H5 files.';
            table.CellEditCallback = @(src,event)roiExtractChannelTableEdited(app, nodeId, src, event);
            table.Layout.Row = 2;
            table.Layout.Column = [2 4];

            selectedCount = sum(cellfun(@(value)logical(value), channelData(:,1)));
            selectAllButton = uibutton(grid, 'push', ...
                'Text', ternary(app, selectedCount == size(channelData,1), 'Deselect all', 'Select all'), ...
                'Enable', ternary(app, useSymbolic || ~hasInventory, 'off', 'on'), ...
                'ButtonPushedFcn', @(src,~)roiExtractToggleAllChannels(app, nodeId, table, src));
            selectAllButton.Layout.Row = 1;
            selectAllButton.Layout.Column = 4;
            table.UserData = selectAllButton;

            hint = uilabel(grid, 'Text', ternary(app, useSymbolic, ...
                ternary(app, hasInventory, ...
                    'Symbolic mode: all upstream source channels will be extracted.', ...
                    'No concrete channel inventory is available yet; source channels will be resolved at run time.'), ...
                'Manual mode: checked channels are stored in extractChannels.'), ...
                'FontColor', [0.35 0.35 0.35], 'Interpreter', 'none');
            hint.Layout.Row = 3;
            hint.Layout.Column = [2 4];
        end

        function channels = roiExtractAvailableChannels(app)
            channels = runtimeConcreteChannels(app);
            channels = channels(~cellfun(@(s)startsWith(strtrim(char(string(s))), '<'), channels));
            channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
        end

        function tf = roiExtractUsesSymbolicSource(app, node)
            nodeId = char(string(getField(app, node, 'id', '')));
            value = [];
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            if isstruct(runtimeParams) && isfield(runtimeParams, 'extractChannels')
                value = runtimeParams.extractChannels;
            else
                p = getField(app, node, 'params', struct());
                if isstruct(p) && isfield(p, 'extractChannels')
                    value = p.extractChannels;
                elseif isstruct(p) && isfield(p, 'channels')
                    value = p.channels;
                end
            end
            txt = strtrim(choiceScalarText(app, value));
            tf = isempty(txt) || isSymbolicStoredBinding(app, txt) || startsWith(txt, '<') || strcmpi(txt, 'all') || strcmpi(txt, '<all>');
        end

        function data = roiExtractChannelTableData(app, node, channels, useSymbolic)
            selected = roiExtractSelectedChannels(app, node, channels, useSymbolic);
            data = cell(numel(channels), 2);
            for i = 1:numel(channels)
                ch = char(string(channels{i}));
                data{i,1} = useSymbolic || any(strcmp(selected, ch));
                data{i,2} = ch;
            end
        end

        function selected = roiExtractSelectedChannels(app, node, channels, useSymbolic)
            if useSymbolic
                selected = channels;
                return;
            end
            nodeId = char(string(getField(app, node, 'id', '')));
            value = [];
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            if isstruct(runtimeParams) && isfield(runtimeParams, 'extractChannels')
                value = runtimeParams.extractChannels;
            else
                p = getField(app, node, 'params', struct());
                if isstruct(p) && isfield(p, 'extractChannels')
                    value = p.extractChannels;
                elseif isstruct(p) && isfield(p, 'channels')
                    value = p.channels;
                end
            end
            selected = normalizeChannelSelectionValue(app, value);
            if isempty(selected)
                selected = channels;
            end
        end

        function channels = normalizeChannelSelectionValue(app, value) %#ok<INUSD>
            channels = {};
            if isempty(value)
                return;
            end
            if iscell(value)
                channels = cellstr(string(value(:)'));
            elseif isstring(value)
                channels = cellstr(value(:)');
            elseif ischar(value)
                txt = strtrim(value);
                if any(strcmpi(txt, {'all','<all>','*',':'})) || startsWith(txt, '@') || startsWith(txt, '<')
                    channels = {};
                else
                    parts = regexp(txt, '[,;]', 'split');
                    channels = cellfun(@strtrim, parts, 'UniformOutput', false);
                end
            elseif isnumeric(value)
                channels = cellstr(string(value(:)'));
            end
            channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
        end

        function ids = upstreamNodeIds(app, nodeId)
            ids = {};
            nodeId = char(string(nodeId));
            try
                edges = app.Data.edges;
                for i = 1:numel(edges)
                    toId = char(string(getField(app, edges(i), 'to', '')));
                    fromId = char(string(getField(app, edges(i), 'from', '')));
                    if strcmp(toId, nodeId) && ~isempty(fromId)
                        ids{end+1} = fromId; %#ok<AGROW>
                    end
                end
            catch
                ids = {};
            end
            ids = unique(ids, 'stable');
        end

        function symbolic = roiExtractSourceSymbolicBinding(app, node)
            symbolic = '@source';
            spec = struct('type', 'channel', 'role', 'source');
            try
                resources = upstreamCompatibleResources(app, node, spec);
            catch
                resources = struct([]);
            end
            if isempty(resources)
                return;
            end
            symbols = cell(1, numel(resources));
            for i = 1:numel(resources)
                symbols{i} = char(string(getField(app, resources(i), 'symbol', '')));
            end
            idx = find(~cellfun(@isempty, symbols), 1);
            if isempty(idx)
                return;
            end
            symbolic = ['@' symbols{idx}];
        end

        function roiExtractSourceModeChanged(app, nodeId, modeValue)
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            if strcmpi(char(string(modeValue)), 'symbolic')
                symbolic = roiExtractSourceSymbolicBinding(app, app.Data.nodes(idx));
                app.Data.nodes(idx).params.extractChannels = symbolic;
                runtimeParams.extractChannels = symbolic;
            else
                channels = roiExtractAvailableChannels(app);
                if isempty(channels)
                    app.Data.nodes(idx).params.extractChannels = '@source';
                    runtimeParams.extractChannels = '@source';
                    setRuntimeNodeParams(app, nodeId, runtimeParams);
                    markPipelineDirty(app, true);
                    refreshModuleTabs(app);
                    refreshValidationReport(app, false);
                    redrawGraph(app);
                    return;
                end
                app.Data.nodes(idx).params.extractChannels = channels;
                runtimeParams.extractChannels = channels;
            end
            setRuntimeNodeParams(app, nodeId, runtimeParams);
            markPipelineDirty(app, true);
            refreshModuleTabs(app);
            refreshValidationReport(app, false);
            redrawGraph(app);
        end

        function roiExtractChannelTableEdited(app, nodeId, table, event)
            if isempty(event.Indices) || event.Indices(2) ~= 1
                return;
            end
            data = table.Data;
            if isempty(data)
                return;
            end
            selected = {};
            for i = 1:size(data,1)
                try
                    if logical(data{i,1})
                        selected{end+1} = char(string(data{i,2})); %#ok<AGROW>
                    end
                catch
                end
            end
            updateRoiExtractSelectAllButton(app, table);
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            app.Data.nodes(idx).params.extractChannels = selected;
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            runtimeParams.extractChannels = selected;
            setRuntimeNodeParams(app, nodeId, runtimeParams);
            markPipelineDirty(app, true);
            refreshValidationReport(app, false);
        end

        function roiExtractToggleAllChannels(app, nodeId, table, button)
            data = table.Data;
            if isempty(data)
                return;
            end
            checked = cellfun(@(value)logical(value), data(:,1));
            selectAll = ~all(checked);
            selected = {};
            for i = 1:size(data,1)
                data{i,1} = selectAll;
                if selectAll
                    selected{end+1} = char(string(data{i,2})); %#ok<AGROW>
                end
            end
            table.Data = data;
            button.Text = ternary(app, selectAll, 'Deselect all', 'Select all');

            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            app.Data.nodes(idx).params.extractChannels = selected;
            runtimeParams = getRuntimeNodeParams(app, nodeId);
            runtimeParams.extractChannels = selected;
            setRuntimeNodeParams(app, nodeId, runtimeParams);
            markPipelineDirty(app, true);
            refreshValidationReport(app, false);
        end

        function updateRoiExtractSelectAllButton(app, table)
            data = table.Data;
            if isempty(data)
                return;
            end
            button = table.UserData;
            if isempty(button) || ~isvalid(button)
                return;
            end
            checked = cellfun(@(value)logical(value), data(:,1));
            button.Text = ternary(app, all(checked), 'Deselect all', 'Select all');
        end

        function ctrl = createBindingControl(app, parent, node, param, value, choices, direction, editable)
            enableState = ternary(app, editable, 'on', 'off');
            if strcmpi(node.pkg,'lstm_timeseries')&&strcmp(param,'featureCount')
                ctrl=uigridlayout(parent,[1 2]);ctrl.RowHeight={24};ctrl.ColumnWidth={'1x',100};ctrl.Padding=[0 0 0 0];
                uilabel(ctrl,'Text',sprintf('%d input traces',node.params.featureCount));
                uibutton(ctrl,'Text','Add input','Tag','AddNumericInput','Enable',enableState, ...
                    'ButtonPushedFcn',@(~,~)changeNumericInputList(app,node,''));
                return;
            end
            isInput = strcmpi(char(string(direction)), 'Input');
            if isInput && isDataSeriesVariableBindingParamForUi(app, param)
                ctrl = createDataSeriesVariableBindingControl(app, parent, node, param, value, choices, direction, editable);
                return;
            end
            if isInput && strcmpi(char(string(param)), 'zStackChannelNames')
                ctrl = uieditfield(parent, 'text');
                zValue = normalizeZStackBindingValue(app, value);
                if isempty(zValue)
                    zValue = normalizeZStackBindingValue(app, getField(app, getField(app, node, 'params', struct()), 'zStackChannelNames', []));
                end
                if isempty(zValue)
                    defaults = defaultNodeParams(app, getField(app, node, 'type', ''), getField(app, node, 'pkg', ''));
                    if isstruct(defaults) && isfield(defaults, 'zStackChannelNames')
                        zValue = normalizeZStackBindingValue(app, defaults.zStackChannelNames);
                    end
                end
                if isempty(zValue)
                    zValue = defaultZStackBindingValue(app);
                end
                if ~isempty(zValue)
                    ctrl.Value = bindingMultiValueToDisplay(app, zValue);
                    persistZStackBindingDefault(app, node, zValue);
                else
                    ctrl.Value = bindingMultiValueToDisplay(app, value);
                end
                if isempty(ctrl.Value)
                    ctrl.Value = '<all>';
                end
                ctrl.Enable = enableState;
                ctrl.Tooltip = 'Comma-separated z-stack channel list or pattern, e.g. DIC_Z$$$ for DIC_Z001...DIC_Z100.';
                ctrl.ValueChangedFcn = @(src,~)bindingControlChanged(app, node, param, direction, src.Value);
                return;
            end
            if isInput || ~isempty(choices)
                displayValue = choiceScalarText(app, value);
                placeholder = '<unconfigured>';
                invalidSymbolicSource = isInput && isSymbolicBindingLabel(app, displayValue) && ...
                    ~symbolicBindingIsActive(app, displayValue);
                invalidStoredValue = invalidSymbolicSource;
                if isempty(choices)
                    choices = {displayValue};
                end
                choices = flattenChoiceList(app, choices);
                choices = choices(~cellfun(@isempty, choices));
                if isInput && isempty(displayValue)
                    choices = [{placeholder} choices]; %#ok<AGROW>
                    displayValue = placeholder;
                elseif ~isempty(displayValue) && ~any(strcmp(choices, displayValue))
                    % Never make the UI look as if the first available
                    % binding were selected while the model still stores a
                    % different value. Keep the stored value visible until
                    % the user explicitly chooses another binding.
                    invalidStoredValue = isInput && ~app.RuntimeInventoryMetadataOnly;
                    choices = [{displayValue} choices]; %#ok<AGROW>
                end
                if isempty(choices)
                    choices = {'<unresolved>'};
                end
                if isempty(displayValue) || ~any(strcmp(choices, displayValue))
                    if isInput && any(strcmp(choices, placeholder))
                        displayValue = placeholder;
                    else
                        displayValue = choices{1};
                    end
                end
                ctrl = uidropdown(parent);
                if invalidStoredValue
                    itemLabels = choices;
                    invalidIndex = find(strcmp(choices, displayValue), 1);
                    if ~isempty(invalidIndex)
                        itemLabels{invalidIndex} = ['[INVALID] ' displayValue];
                    end
                    ctrl.Items = itemLabels;
                    ctrl.ItemsData = choices;
                    ctrl.BackgroundColor = [1.00 0.88 0.88];
                    if invalidSymbolicSource
                        ctrl.Tooltip = ['This binding references a module excluded from this run. ' ...
                            'Select an explicit existing input, or include the producer module in the run.'];
                    else
                        ctrl.Tooltip = ['Stored channel "' displayValue ...
                            '" is absent from the authoritative runtime inventory. Select a valid channel.'];
                    end
                else
                    ctrl.Items = choices;
                end
                ctrl.Value = displayValue;
                ctrl.Enable = enableState;
                ctrl.ValueChangedFcn = @(src,~)bindingControlChanged(app, node, param, direction, src.Value);
                if isInput
                    ctrl.DropDownOpeningFcn = @(src,~)loadBindingChoicesOnDemand(app, src, node, param);
                end
                return;
            end

            ctrl = uieditfield(parent, 'text');
            ctrl.Value = choiceScalarText(app, value);
            ctrl.Enable = enableState;
            ctrl.ValueChangedFcn = @(src,~)bindingControlChanged(app, node, param, direction, src.Value);
        end

        function ctrl = createDataSeriesVariableBindingControl(app, parent, node, param, value, choices, direction, editable)
            enableState = ternary(app, editable, 'on', 'off');
            ctrl = uigridlayout(parent, [2 1]);
            ctrl.RowHeight = {24, 24};
            ctrl.ColumnWidth = {'1x'};
            ctrl.Padding = [0 0 0 0];
            ctrl.RowSpacing = 3;

            [seriesName, variableName] = splitDataSeriesVariableBindingForUi(app, value);
            if isempty(seriesName)
                seriesName = '';
            end
            if isempty(variableName)
                variableName = '<variable>';
            end

            seriesChoices = dataSeriesVariableSeriesChoicesForUi(app, choices, seriesName);
            if strcmpi(node.pkg,'lstm_timeseries')
                inventory=numericTraceInventory(app);
                seriesChoices=unique([seriesChoices unique({inventory.group},'stable')],'stable');
            end
            if isempty(seriesChoices)
                seriesChoices = {seriesName};
            end
            seriesChoices = seriesChoices(~cellfun(@isempty, seriesChoices));
            if isempty(seriesChoices)
                seriesChoices = {'<dataseries>'};
            end
            seriesDrop = uidropdown(ctrl);
            seriesDrop.Items = seriesChoices;
            if any(strcmp(seriesChoices, seriesName))
            seriesDrop.Value = seriesName;
            else
                seriesDrop.Value = seriesChoices{1};
            end
            seriesDrop.Enable = enableState;
            seriesDrop.Editable = 'on';
            seriesDrop.DropDownOpeningFcn = @(src,~)loadSeriesChoicesOnDemand(app, src, node, param);
            seriesDrop.Tooltip = dataSeriesSeriesFieldTooltipForUi(app, seriesChoices);
            seriesDrop.Layout.Row = 1;
            seriesDrop.Layout.Column = 1;

            variableChoices = dataSeriesVariableDropdownChoicesForUi(app, seriesDrop.Value, variableName);
            if strcmpi(node.pkg,'lstm_timeseries')
                inventory=numericTraceInventory(app);group=concreteDataSeriesNameForVariableChoices(app,seriesDrop.Value);
                keep=strcmp({inventory.group},group);
                if any(keep),variableChoices=unique({inventory(keep).variable},'stable');end
                if ~ismember(variableName,variableChoices),variableChoices=[{variableName} variableChoices];end
            end
            varDrop = uidropdown(ctrl);
            varDrop.Items = variableChoices;
            if any(strcmp(variableChoices, variableName))
                varDrop.Value = variableName;
            else
                varDrop.Value = variableChoices{1};
            end
            varDrop.Enable = enableState;
            % Upstream modules can expose a series before its variables exist.
            varDrop.Editable = 'on';
            varDrop.DropDownOpeningFcn = @(src,~)loadVariableChoicesOnDemand(app, src, seriesDrop);
            varDrop.Tooltip = 'Variable declared inside the selected dataseries. Choices are sampled from existing ROI dataseries when available.';
            varDrop.Layout.Row = 2;
            varDrop.Layout.Column = 1;
            if strcmpi(node.pkg,'lstm_timeseries')
                seriesDrop.Tag=['NumericSeries_' param];varDrop.Tag=['NumericVariable_' param];
                columns=numericBindingColumns(app,value);number=regexp(param,'\d+$','match','once');key=['featureColumn' number];
                component=getField(app,getField(app,node,'params',struct()),key,1);
                if app.RuntimeInventoryMetadataOnly || columns>1
                    ctrl.RowHeight={24 24 24};componentDrop=uidropdown(ctrl,'Tag',['NumericComponent_' param]);
                    count = max(1, columns);
                    componentDrop.Items=arrayfun(@(i)sprintf('Component %d',i),1:count,'UniformOutput',false);
                    componentDrop.ItemsData=1:count;
                    if component>count
                        componentDrop.Items{end+1}=sprintf('Component %d (unavailable)',component);
                        componentDrop.ItemsData(end+1)=component;
                        if app.RuntimeInventoryMetadataOnly
                            componentDrop.Items{end}=sprintf('Component %d',component);
                        end
                    end
                    componentDrop.Value=component;componentDrop.Enable=enableState;
                    componentDrop.Tooltip='This variable has several scalar values per frame. Choose the component used by this input.';
                    componentDrop.Layout.Row=3;componentDrop.ValueChangedFcn=@(src,~)numericComponentChanged(app,node,key,src.Value);
                    componentDrop.DropDownOpeningFcn=@(src,~)loadNumericComponentsOnDemand(app,src,value);
                elseif columns==0&&component>1
                    state = ternary(app, app.RuntimeInventoryMetadataOnly, 'inventory not loaded', 'source unavailable');
                    ctrl.RowHeight={24 24 24};label=uilabel(ctrl,'Text',sprintf('Saved component %d (%s)',component,state));label.Layout.Row=3;
                end
            end

            seriesDrop.ValueChangedFcn = @(src,~)dataSeriesVariableSeriesChanged(app, node, param, direction, src.Value, varDrop);
            varDrop.ValueChangedFcn = @(src,~)dataSeriesVariableNameChanged(app, node, param, direction, seriesDrop.Value, src.Value);
        end

        function tf = isDataSeriesVariableBindingParamForUi(app, param) %#ok<INUSD>
            tf = any(strcmpi(char(string(param)), {'labelVariable','fluorescenceVariable'})) || ...
                ~isempty(regexp(char(string(param)),'^feature\d+$','once'));
        end

        function [seriesName, variableName] = splitDataSeriesVariableBindingForUi(app, value) %#ok<INUSD>
            seriesName = '';
            variableName = '';
            txt = strtrim(char(string(value)));
            if isempty(txt) || any(strcmpi(txt, {'auto','<auto>','<unconfigured>','<unresolved>'}))
                return;
            end
            if startsWith(txt, '<') && endsWith(txt, '>') && contains(txt, ' output from ')
                inner = strtrim(txt(2:end-1));
                tokens = regexp(inner, '^(.+?\s+output\s+from\s+[^/]+)(?:\s*/\s*(.+))?$', 'tokens', 'once');
                if isempty(tokens) || isempty(tokens{1})
                    seriesName = txt;
                    return;
                end
                if numel(tokens) < 2 || isempty(tokens{2})
                    seriesName = txt;
                    return;
                end
                payload = strtrim(tokens{2});
                parts = regexp(payload, '\s*/\s*', 'split');
                seriesName = ['<' strtrim(tokens{1}) ' / ' strtrim(parts{1}) '>'];
                if numel(parts) >= 2
                    variableName = strtrim(strjoin(parts(2:end), ' / '));
                end
                return;
            end
            parts = regexp(txt, '\s*/\s*', 'split');
            if numel(parts) >= 2
                seriesName = strtrim(parts{1});
                variableName = strtrim(strjoin(parts(2:end), ' / '));
            else
                seriesName = txt;
            end
        end

        function choices = dataSeriesVariableSeriesChoicesForUi(app, choices, currentSeries)
            raw = flattenChoiceList(app, choices);
            out = {};
            for i = 1:numel(raw)
                txt = strtrim(char(string(raw{i})));
                if startsWith(txt, '<resource:')
                    continue;
                end
                if startsWith(txt, '<') && endsWith(txt, '>') && contains(txt, ' output from ')
                    out{end+1} = stripDataSeriesVariableSuffixFromSymbolicLabel(app, txt); %#ok<AGROW>
                else
                    [seriesName, ~] = splitDataSeriesVariableBindingForUi(app, txt);
                    if ~isempty(seriesName)
                        out{end+1} = seriesName; %#ok<AGROW>
                    end
                end
            end
            keepCurrentSeries = ~isempty(currentSeries);
            if keepCurrentSeries && isSymbolicDataSeriesLabelForUi(app, currentSeries) && ...
                    ~symbolicSeriesSourceExistsForUi(app, currentSeries) && ~isempty(out)
                keepCurrentSeries = false;
            end
            if keepCurrentSeries
                out = [{currentSeries} out]; %#ok<AGROW>
            end
            choices = unique(out(~cellfun(@isempty, out)), 'stable');
        end

        function tf = symbolicSeriesSourceExistsForUi(app, seriesName)
            sourceNodeId = symbolicSeriesSourceNodeForUi(app, seriesName);
            if isempty(sourceNodeId)
                tf = true;
                return;
            end
            tf = ~isempty(app.Data.nodes) && any(strcmp(cellstr(string({app.Data.nodes.id})), sourceNodeId));
        end

        function label = stripDataSeriesVariableSuffixFromSymbolicLabel(app, label) %#ok<INUSD>
            label = strtrim(char(string(label)));
            if ~(startsWith(label, '<') && endsWith(label, '>'))
                return;
            end
            inner = strtrim(label(2:end-1));
            tokens = regexp(inner, '^(.+?\s+output\s+from\s+[^/]+)(?:\s*/\s*(.+))?$', 'tokens', 'once');
            if isempty(tokens) || numel(tokens) < 2 || isempty(tokens{2}) || ~contains(tokens{2}, '/')
                return;
            end
            parts = regexp(strtrim(tokens{2}), '\s*/\s*', 'split');
            label = ['<' strtrim(tokens{1}) ' / ' strtrim(parts{1}) '>'];
        end

        function choices = dataSeriesVariableDropdownChoicesForUi(app, seriesName, currentVariable)
            choices = {};
            concreteSeries = concreteDataSeriesNameForVariableChoices(app, seriesName);
            if ~isempty(concreteSeries) && ~startsWith(concreteSeries, '@') && ...
                    ~(startsWith(concreteSeries, '<') && endsWith(concreteSeries, '>'))
                choices = runtimeDataSeriesVariableNames(app, concreteSeries);
            end
            if isempty(choices) && isSymbolicDataSeriesLabelForUi(app, seriesName)
                choices = inferredDataSeriesVariablesForSymbolicSeries(app, seriesName);
            end
            if ~isempty(currentVariable) && ~strcmp(currentVariable, '<variable>') && ...
                    ~any(strcmp(choices, currentVariable))
                choices = [{currentVariable} choices]; %#ok<AGROW>
            end
            if isempty(choices)
                choices = {'<variable>'};
            end
            choices = unique(choices(~cellfun(@isempty, choices)), 'stable');
        end

        function seriesName = concreteDataSeriesNameForVariableChoices(app, value)
            seriesName = strtrim(char(string(value)));
            if startsWith(seriesName, '<') && endsWith(seriesName, '>') && contains(seriesName, ' output from ')
                inner = strtrim(seriesName(2:end-1));
                tokens = regexp(inner, '^.+?\s+output\s+from\s+[^/]+(?:\s*/\s*(.+))?$', 'tokens', 'once');
                if ~isempty(tokens) && ~isempty(tokens{1})
                    seriesName = strtrim(tokens{1});
                end
            end
            seriesName = dataSeriesNameFromVariableBindingForUi(app, seriesName);
        end

        function tf = isSymbolicDataSeriesLabelForUi(app, value) %#ok<INUSD>
            value = strtrim(char(string(value)));
            tf = startsWith(value, '<') && endsWith(value, '>') && contains(value, ' output from ');
        end

        function choices = inferredDataSeriesVariablesForSymbolicSeries(app, seriesName)
            choices = {};
            sourceNodeId = symbolicSeriesSourceNodeForUi(app, seriesName);
            if isempty(sourceNodeId)
                return;
            end
            try
                ids = cellstr(string({app.Data.nodes.id}));
                idx = find(strcmp(ids, sourceNodeId), 1);
                if isempty(idx)
                    return;
                end
                sourceNode = app.Data.nodes(idx);
                pkg = lower(char(string(getField(app, sourceNode, 'pkg', ''))));
                if strcmp(pkg, 'computemetrics')
                    choices = inferComputeMetricsVariableChoicesForUi(app, sourceNode);
                elseif strcmp(pkg,'trackmotherlineageviterbi')
                    choices={'Area_Mother','Area_Bud'};
                elseif any(strcmp(pkg, {'cnn_lstm','cnn','lstm_timeseries'}))
                    choices = {'id','labels'};
                    p=getField(app,sourceNode,'params',struct());
                    classes=getField(app,p,'classes',{});
                    for k=1:numel(classes)
                        choices{end+1}=['prob_' char(string(classes{k}))]; %#ok<AGROW>
                    end
                end
            catch
                choices = {};
            end
        end

        function sourceNodeId = symbolicSeriesSourceNodeForUi(app, seriesName) %#ok<INUSD>
            sourceNodeId = '';
            txt = strtrim(char(string(seriesName)));
            if startsWith(txt, '<') && endsWith(txt, '>')
                inner = strtrim(txt(2:end-1));
                tokens = regexp(inner, '^.+?\s+output\s+from\s+([^/\s]+)', 'tokens', 'once');
                if ~isempty(tokens)
                    sourceNodeId = strtrim(tokens{1});
                end
            elseif startsWith(txt, '@resource:')
                parts = strsplit(txt, ':');
                if numel(parts) >= 3
                    sourceNodeId = strtrim(parts{3});
                    sourceNodeId = dataSeriesNameFromVariableBindingForUi(app, sourceNodeId);
                end
            end
        end

        function choices = inferComputeMetricsVariableChoicesForUi(app, node)
            p = getField(app, node, 'params', struct());
            maskCount = computeMetricsMaskSlotCountForNode(app, node);
            scoreCount = computeMetricsScoreSlotCountForNode(app, node);
            channels = {};
            for i = 1:scoreCount
                key = sprintf('channel%d_name', i);
                name = choiceScalarText(app, getField(app, p, key, ''));
                if isempty(name) || any(strcmpi(name, {'N/A','none','<unconfigured>'}))
                    continue;
                end
                channels{end+1} = name; %#ok<AGROW>
            end

            choices = {};
            metricPrefixes = {'Mean','Tot','MeanTop','TotTop','Mean_Bckg','MeanNoBckg'};
            for m = 1:maskCount
                maskName = choiceScalarText(app, getField(app, p, sprintf('mask%d_name', m), ''));
                if isempty(maskName) || any(strcmpi(maskName, {'N/A','none','<unconfigured>'}))
                    continue;
                end
                maskLabel = choiceScalarText(app, getField(app, p, sprintf('mask%d_label', m), sprintf('mask%d', m)));
                if isempty(maskLabel)
                    maskLabel = sprintf('mask%d', m);
                end
                choices{end+1} = ['MaskIdx_' safeMetricVariableTokenForUi(app, maskLabel)]; %#ok<AGROW>
                for c = 1:numel(channels)
                    for k = 1:numel(metricPrefixes)
                        choices{end+1} = safeMetricVariableNameForUi(app, sprintf('%s_%s_%s', metricPrefixes{k}, channels{c}, maskLabel)); %#ok<AGROW>
                    end
                end
                for a = 1:numel(channels)
                    for b = a+1:numel(channels)
                        choices{end+1} = safeMetricVariableNameForUi(app, sprintf('Ratio_Mean_NoBckg_%s_%s_%s', channels{a}, channels{b}, maskLabel)); %#ok<AGROW>
                    end
                end
            end
            choices = unique(choices(~cellfun(@isempty, choices)), 'stable');
        end

        function token = safeMetricVariableTokenForUi(app, value) %#ok<INUSD>
            token = char(string(value));
            try
                token = matlab.lang.makeValidName(token);
            catch
                token = regexprep(token, '[^A-Za-z0-9_]', '_');
                if isempty(regexp(token, '^[A-Za-z]', 'once'))
                    token = ['x' token];
                end
            end
        end

        function name = safeMetricVariableNameForUi(app, value)
            name = safeMetricVariableTokenForUi(app, value);
        end

        function tip = dataSeriesSeriesFieldTooltipForUi(app, choices) %#ok<INUSD>
            tip = 'Dataseries name or symbolic upstream source. Example: channel_quantification or @resource:metrics:processor_computemetrics_10.';
            if ~isempty(choices)
                sample = choices(1:min(numel(choices), 5));
                tip = [tip newline 'Available sources: ' strjoin(sample, ', ')];
            end
        end

        function dataSeriesVariableSeriesChanged(app, node, param, direction, seriesName, variableDrop)
            currentVariable = '';
            try
                currentVariable = char(string(variableDrop.Value));
            catch
            end
            variableChoices = dataSeriesVariableDropdownChoicesForUi(app, seriesName, currentVariable);
            if strcmpi(node.pkg,'lstm_timeseries')
                inventory=numericTraceInventory(app);group=concreteDataSeriesNameForVariableChoices(app,seriesName);
                keep=strcmp({inventory.group},group);
                if any(keep)
                    variableChoices=unique({inventory(keep).variable},'stable');
                    if ~ismember(currentVariable,variableChoices)
                        currentVariable='<variable>';variableChoices=[{'<variable>'} variableChoices];
                    end
                end
            end
            variableDrop.Items = variableChoices;
            if any(strcmp(variableChoices, currentVariable))
                variableName = currentVariable;
            else
                variableName = variableChoices{1};
            end
            variableDrop.Value = variableName;
            seriesName = dataSeriesVariableSeriesStorageValueForUi(app, seriesName);
            if isempty(variableName) || any(strcmpi(variableName, {'<variable>','auto','<auto>'}))
                value = seriesName;
            else
                value = [seriesName ' / ' strtrim(char(string(variableName)))];
            end
            bindingControlChanged(app, node, param, direction, value);
        end

        function dataSeriesVariableNameChanged(app, node, param, direction, seriesName, variableName)
            seriesName = dataSeriesVariableSeriesStorageValueForUi(app, seriesName);
            variableName = strtrim(char(string(variableName)));
            if isempty(seriesName)
                value = variableName;
            elseif isempty(variableName) || any(strcmpi(variableName, {'<variable>','auto','<auto>'}))
                value = seriesName;
            else
                value = [seriesName ' / ' variableName];
            end
            bindingControlChanged(app, node, param, direction, value);
        end

        function value = dataSeriesVariableSeriesStorageValueForUi(app, seriesName)
            value = strtrim(char(string(seriesName)));
            if startsWith(value, '<') && endsWith(value, '>') && contains(value, ' output from ')
                value = symbolicBindingValueFromLabel(app, value);
            end
        end

        function tf = isZStackPlaceholderBinding(app, value) %#ok<INUSD>
            value = lower(choiceScalarText(app, value));
            tf = isempty(value) || any(strcmp(value, {'<z_stack output>','<z-stack output>', ...
                '@z_stack','@z-stack','@z_stack output','@z-stack output'}));
        end

        function valueOut = normalizeZStackBindingValue(app, value)
            valueOut = {};
            if isZStackPatternValue(app, value)
                valueOut = strtrim(char(string(value)));
                return;
            end
            channels = normalizeZStackBindingChannels(app, value);
            if numel(channels) >= 2
                pattern = inferZStackPatternFromChannels(app, channels);
                if ~isempty(pattern)
                    valueOut = pattern;
                else
                    valueOut = channels;
                end
            end
        end

        function channels = normalizeZStackBindingChannels(app, value)
            channels = expandZStackPatternChannels(app, value);
            if numel(channels) >= 2
                channels = sortZStackBindingChannels(app, channels);
                return;
            end
            channels = normalizeChannelSelectionValue(app, value);
            channels = filterConcreteZStackChannels(app, channels);
            if numel(channels) >= 2
                channels = sortZStackBindingChannels(app, channels);
                return;
            end
            if isZStackNonConcreteValue(app, value)
                channels = defaultZStackBindingChannels(app);
            else
                channels = {};
            end
        end

        function channels = expandZStackPatternChannels(app, value)
            channels = {};
            patterns = normalizeChannelSelectionValue(app, value);
            if isempty(patterns)
                return;
            end
            available = {};
            try
                available = runtimeValidationRoiChannels(app);
            catch
                available = {};
            end
            if isempty(available)
                return;
            end
            available = cellstr(string(available(:)'));
            for i = 1:numel(patterns)
                pat = strtrim(char(string(patterns{i})));
                if isempty(pat) || ~(contains(pat, '$') || contains(pat, '#') || contains(pat, '*'))
                    continue;
                end
                rx = zStackPatternToRegexp(app, pat);
                for j = 1:numel(available)
                    name = char(string(available{j}));
                    if ~isempty(regexp(name, rx, 'once'))
                        channels{end+1} = name; %#ok<AGROW>
                    end
                end
            end
            channels = filterConcreteZStackChannels(app, unique(channels, 'stable'));
            channels = sortZStackBindingChannels(app, channels);
        end

        function rx = zStackPatternToRegexp(app, pat) %#ok<INUSD>
            pat = char(string(pat));
            rx = '^';
            i = 1;
            while i <= numel(pat)
                ch = pat(i);
                if ch == '$' || ch == '#'
                    j = i;
                    while j <= numel(pat) && (pat(j) == '$' || pat(j) == '#')
                        j = j + 1;
                    end
                    rx = [rx '\d{' num2str(j - i) '}']; %#ok<AGROW>
                    i = j;
                elseif ch == '*'
                    rx = [rx '.*']; %#ok<AGROW>
                    i = i + 1;
                else
                    rx = [rx regexptranslate('escape', ch)]; %#ok<AGROW>
                    i = i + 1;
                end
            end
            rx = [rx '$'];
        end

        function tf = isZStackPatternValue(app, value)
            tf = false;
            if isempty(value)
                return;
            end
            try
                if iscell(value) || (isstring(value) && ~isscalar(value))
                    return;
                end
                txt = strtrim(char(string(value)));
            catch
                return;
            end
            if isempty(txt) || isAllChannelSelectorText(app, txt) || startsWith(txt, '<') || startsWith(txt, '@')
                return;
            end
            tf = contains(txt, '$') || contains(txt, '#') || contains(txt, '*');
        end

        function pattern = inferZStackPatternFromChannels(app, channels)
            pattern = '';
            channels = sortZStackBindingChannels(app, filterConcreteZStackChannels(app, normalizeChannelSelectionValue(app, channels)));
            if numel(channels) < 2
                return;
            end
            tokens = cell(1, numel(channels));
            for i = 1:numel(channels)
                tokens{i} = regexp(char(string(channels{i})), '^(.*?)(\d+)$', 'tokens', 'once');
                if isempty(tokens{i})
                    pattern = '';
                    return;
                end
            end
            prefix = tokens{1}{1};
            width = numel(tokens{1}{2});
            for i = 2:numel(tokens)
                if ~strcmp(tokens{i}{1}, prefix) || numel(tokens{i}{2}) ~= width
                    pattern = '';
                    return;
                end
            end
            pattern = [prefix repmat('$', 1, width)];
        end

        function channels = filterConcreteZStackChannels(app, channels) %#ok<INUSD>
            if isempty(channels)
                channels = {};
                return;
            end
            channels = cellstr(string(channels(:)'));
            keep = false(1, numel(channels));
            for i = 1:numel(channels)
                txt = strtrim(char(string(channels{i})));
                low = lower(txt);
                if isempty(txt) || startsWith(txt, '<') || startsWith(txt, '@') || ...
                        any(strcmpi(txt, {'all','*',':','<all>','auto','none','n/a'}))
                    continue;
                end
                hasZNumber = ~isempty(regexp(low, 'z[^a-z0-9]*\d+|\d+[^a-z0-9]*z', 'once')) || ...
                    ~isempty(regexp(low, '(^|_)dic[_-]?z?\d+$|(^|_)z\d+$|dic_z\d+', 'once'));
                isBad = startsWith(low, 'results_') || contains(low, 'prob') || ...
                    contains(low, 'mask') || contains(low, 'focus') || contains(low, 'cell_of_interest');
                keep(i) = hasZNumber && ~isBad;
            end
            channels = unique(channels(keep), 'stable');
        end

        function tf = isZStackNonConcreteValue(app, value)
            tf = true;
            if isempty(value)
                return;
            end
            try
                txt = strtrim(char(string(value)));
                if isempty(txt) || isZStackPlaceholderBinding(app, txt) || isAllChannelSelectorText(app, txt) || ...
                        startsWith(txt, '@') || startsWith(txt, '<')
                    return;
                end
            catch
            end
            tf = isempty(filterConcreteZStackChannels(app, normalizeChannelSelectionValue(app, value)));
        end

        function channels = defaultZStackBindingChannels(app)
            channels = {};
            try
                channels = runtimeValidationRoiChannels(app);
            catch
                channels = {};
            end
            channels = filterConcreteZStackChannels(app, normalizeChannelSelectionValue(app, channels));
            if isempty(channels)
                return;
            end
            channels = sortZStackBindingChannels(app, channels);
        end

        function valueOut = defaultZStackBindingValue(app)
            valueOut = {};
            channels = defaultZStackBindingChannels(app);
            if isempty(channels)
                return;
            end
            pattern = inferZStackPatternFromChannels(app, channels);
            if ~isempty(pattern)
                valueOut = pattern;
            else
                valueOut = channels;
            end
        end

        function persistZStackBindingDefault(app, node, valueOut)
            if isempty(valueOut)
                return;
            end
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                return;
            end
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            current = getField(app, app.Data.nodes(idx).params, 'zStackChannelNames', []);
            currentConcrete = filterConcreteZStackChannels(app, normalizeChannelSelectionValue(app, current));
            if isZStackPatternValue(app, current) || numel(currentConcrete) >= 2
                return;
            end
            app.Data.nodes(idx).params.zStackChannelNames = valueOut;
            clearRuntimeNodeParam(app, nodeId, 'zStackChannelNames');
            markPipelineDirty(app, true);
        end

        function channels = sortZStackBindingChannels(app, channels) %#ok<INUSD>
            if numel(channels) < 2
                return;
            end
            z = nan(1, numel(channels));
            for i = 1:numel(channels)
                tok = regexp(char(string(channels{i})), '(\d+)$', 'tokens', 'once');
                if ~isempty(tok)
                    z(i) = str2double(tok{1});
                end
            end
            if all(isfinite(z))
                [~, ord] = sort(z);
                channels = channels(ord);
            end
        end

        function rows = numericTraceInventory(app)
            if ~isempty(app.NumericTraceInventory)
                rows=app.NumericTraceInventory;return;
            end
            rows=struct('name',{},'group',{},'variable',{},'columns',{},'roiCount',{});
            [rois,~]=runtimeSampledRoisForDataSeries(app);
            if isempty(rois)&&~isempty(app.ExplicitRuntimeRoiList)
                rois=app.ExplicitRuntimeRoiList;
            end
            for k=1:min(12,numel(rois))
                if app.RuntimeInventoryMetadataOnly && ...
                        (isempty(rois(k).data) || all(arrayfun(@(d)isempty(d.data), rois(k).data)))
                    continue;
                end
                loadRuntimeRoiData(app, rois(k));
                found=classifierBinding.numericSeries(rois(k));
                for j=1:numel(found)
                    idx=find(strcmp({rows.name},found(j).name),1);
                    if isempty(idx)
                        rows(end+1)=found(j); %#ok<AGROW>
                    else
                        rows(idx).columns=min(rows(idx).columns,found(j).columns);
                        rows(idx).roiCount=rows(idx).roiCount+1;
                    end
                end
            end
            app.NumericTraceInventory=rows;
        end

        function columns = numericBindingColumns(app,value)
            [group,variable]=splitDataSeriesVariableBindingForUi(app,value);
            group=concreteDataSeriesNameForVariableChoices(app,group);
            rows=numericTraceInventory(app);
            idx=find(strcmp({rows.group},group)&strcmp({rows.variable},variable),1);
            columns=0;if ~isempty(idx),columns=rows(idx).columns;end
        end

        function height = numericBindingHeight(app,node,param,value)
            height=54;
            if strcmpi(node.pkg,'lstm_timeseries')&&~isempty(regexp(param,'^feature\d+$','once'))
                key=['featureColumn' regexp(param,'\d+$','match','once')];
                component=getField(app,node.params,key,1);
                columns=numericBindingColumns(app,value);
                if app.RuntimeInventoryMetadataOnly||columns>1||(columns==0&&component>1),height=81;end
            end
        end

        function changeNumericInputList(app,node,removeParam)
            idx=find(strcmp({app.Data.nodes.id},node.id),1);
            if isempty(idx),return;end
            old=app.Data.nodes(idx).params;p=lstm_timeseries.parameters([],old);
            selectors=cell(1,p.featureCount);components=ones(1,p.featureCount);
            for k=1:p.featureCount
                selectors{k}=p.(sprintf('feature%d',k));components(k)=p.(sprintf('featureColumn%d',k));
            end
            if isempty(removeParam)
                if numel(selectors)>=128,return;end
                selectors{end+1}='';components(end+1)=1;
            else
                if numel(selectors)<=1,return;end
                k=str2double(regexp(removeParam,'\d+$','match','once'));
                selectors(k)=[];components(k)=[];
            end
            keys=fieldnames(old);
            for k=1:numel(keys)
                if ~isempty(regexp(keys{k},'^feature(?:Column)?\d+$','once'))
                    old=rmfield(old,keys{k});clearRuntimeNodeParam(app,node.id,keys{k});
                end
            end
            old.featureCount=numel(selectors);clearRuntimeNodeParam(app,node.id,'featureCount');
            for k=1:numel(selectors)
                old.(sprintf('feature%d',k))=selectors{k};old.(sprintf('featureColumn%d',k))=components(k);
            end
            app.Data.nodes(idx).params=old;
            markPipelineDirty(app,true);refreshModuleTabs(app);refreshValidationReport(app,false);
        end

        function numericComponentChanged(app,node,key,value)
            idx=find(strcmp({app.Data.nodes.id},node.id),1);
            if isempty(idx),return;end
            app.Data.nodes(idx).params.(key)=double(value);
            clearRuntimeNodeParam(app,node.id,key);
            markPipelineDirty(app,true);refreshValidationReport(app,false);
        end

        function applyClassifierInputBindings(app)
            try
                nodes=app.Data.nodes;idx=find(strcmpi({nodes.pkg},'lstm_timeseries'));
                assert(numel(idx)==1,'Expected one numeric LSTM node.');
                lstm_timeseries.storeInputBindings(app.BindingEditorClassifier,nodes(idx).params);
                if ~isempty(app.BindingEditorCallback),app.BindingEditorCallback();end
                markPipelineDirty(app,false);
                app.ProgressionStatusLabel.Text='Inputs applied. Save the classifier; format and retrain after changing inputs.';
            catch ME
                uialert(app.UIFigure,ME.message,'Input bindings');
            end
        end

        function bindingControlChanged(app, node, param, direction, value)
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx) || isempty(param)
                return;
            end
            focus = captureTabFocus(app);
            d = openRuntimeProgress(app, 'Pipeline bindings', 'Applying binding change...');
            cleanupObj = onCleanup(@()finishStaticParameterRefresh(app, d, [], focus)); %#ok<NASGU>
            param = char(string(param));
            value = strtrim(char(string(value)));
            isInput = strcmpi(char(string(direction)), 'Input');

            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end

            if isInput && strcmpi(param, 'zStackChannelNames')
                zValue = normalizeZStackBindingValue(app, value);
                if isempty(zValue)
                    zValue = defaultZStackBindingValue(app);
                end
                if ~isempty(zValue)
                    app.Data.nodes(idx).params.(param) = zValue;
                else
                    app.Data.nodes(idx).params.(param) = value;
                end
                clearRuntimeNodeParam(app, nodeId, param);
            elseif isAllChannelBindingLabel(app, value)
                app.Data.nodes(idx).params.(param) = 'all';
                clearRuntimeNodeParam(app, nodeId, param);
            elseif isSymbolicBindingLabel(app, value)
                symbolicValue = symbolicBindingValueFromLabel(app, value);
                app.Data.nodes(idx).params.(param) = symbolicValue;
                clearRuntimeNodeParam(app, nodeId, param);
            elseif isempty(value) || any(strcmp(value, {'<unresolved>','<unconfigured>'}))
                if isfield(app.Data.nodes(idx).params, param)
                    app.Data.nodes(idx).params = rmfield(app.Data.nodes(idx).params, param);
                end
                clearRuntimeNodeParam(app, nodeId, param);
            else
                if ~isInput
                    value = normalizeOutputBindingEditValue(app, app.Data.nodes(idx), param, value);
                end
                app.Data.nodes(idx).params.(param) = value;
                clearRuntimeNodeParam(app, nodeId, param);
            end

            numericInput=isInput&&strcmpi(node.pkg,'lstm_timeseries')&&~isempty(regexp(param,'^feature\d+$','once'));
            if numericInput && numericBindingColumns(app,value)==1
                key=['featureColumn' regexp(param,'\d+$','match','once')];
                app.Data.nodes(idx).params.(key)=1;clearRuntimeNodeParam(app,nodeId,key);
            end
            if ~isInput || numericInput
                updateRuntimeProgress(app, d, 'Refreshing dependent binding choices...');
                refreshModuleTabs(app);
            end
            updateRuntimeProgress(app, d, 'Checking pipeline bindings...');
            markPipelineDirty(app, true);
            refreshValidationReport(app);
        end

        function persistMissingBindingDefault(app, node, param, value)
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx) || isempty(param) || isempty(value)
                return;
            end
            param = char(string(param));
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            if isfield(app.Data.nodes(idx).params, param) && ~isempty(app.Data.nodes(idx).params.(param))
                return;
            end
            app.Data.nodes(idx).params.(param) = value;
            markPipelineDirty(app, true);
        end

        function persistMissingOrPlaceholderBindingDefault(app, node, param, value)
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx) || isempty(param) || isempty(value)
                return;
            end
            param = char(string(param));
            if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                app.Data.nodes(idx).params = struct();
            end
            currentValueIsConcrete = isfield(app.Data.nodes(idx).params, param) && ~isempty(app.Data.nodes(idx).params.(param)) && ...
                    ~isZStackPlaceholderBinding(app, app.Data.nodes(idx).params.(param));
            if currentValueIsConcrete && strcmpi(param, 'zStackChannelNames')
                currentValueIsConcrete = ~isAllChannelSelectorText(app, app.Data.nodes(idx).params.(param));
            end
            if currentValueIsConcrete
                return;
            end
            app.Data.nodes(idx).params.(param) = value;
            clearRuntimeNodeParam(app, nodeId, param);
            markPipelineDirty(app, true);
        end

        function value = normalizeOutputBindingEditValue(app, node, param, value) %#ok<INUSD>
            value = strtrim(char(string(value)));
            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
            if strcmp(pkg, 'trackmotherlineageviterbi') && strcmp(char(string(param)), 'outputChannelName')
                value = regexprep(value, '(?i)_(cell|bud|conf|areas)$', '');
            end
        end

        function data = bindingTableData(app, node)
            data = cell(0, 6);
            app.ensureCustomPackagePathForNode(node);
            try
                contract = pipelineNodeContract(node);
            catch
                contract = getField(app, node, 'contract', struct());
            end
            resources = getField(app, contract, 'resources', struct());
            inputs = getField(app, resources, 'in', struct([]));
            outputs = getField(app, resources, 'out', struct([]));
            if strcmpi(getField(app,node,'pkg',''),'lstm_timeseries')
                p=lstm_timeseries.parameters(struct('trainingParam',node.params));
                data(end+1,:)={'Inputs','Selected traces','featureCount',p.featureCount,{},'Add or remove input bindings; count follows this list.'};
            end

            for i = 1:numel(inputs)
                spec = inputs(i);
                if isempty(char(string(getField(app, spec, 'type', ''))))
                    continue;
                end
                param = char(string(getField(app, spec, 'param', '')));
                resourceLabel = bindingResourceLabel(app, node, spec, param);
                value = bindingDisplayedValue(app, node, spec, true);
                choices = bindingInputChoices(app, node, spec, value);
                tooltip = bindingResourceTooltip(app, node, param, resourceLabel, true);
                data(end+1,:) = {'Input', resourceLabel, param, value, {choices}, tooltip}; %#ok<AGROW>
            end

            for i = 1:numel(outputs)
                spec = outputs(i);
                if isempty(char(string(getField(app, spec, 'type', ''))))
                    continue;
                end
                param = char(string(getField(app, spec, 'nameParam', '')));
                if isempty(param)
                    param = char(string(getField(app, spec, 'param', '')));
                end
                if isempty(param)
                    continue;
                end
                resourceLabel = bindingResourceLabel(app, node, spec, param);
                value = outputBindingNameForNode(app, node, spec);
                if isempty(value)
                    value = bindingDisplayedValue(app, node, spec, false);
                end
                tooltip = bindingResourceTooltip(app, node, param, resourceLabel, false);
                data(end+1,:) = {'Output', resourceLabel, param, value, {}, tooltip}; %#ok<AGROW>
            end
        end

        function value = bindingDisplayedValue(app, node, spec, isInput)
            value = '';
            nodeId = char(string(getField(app, node, 'id', '')));
            param = char(string(getField(app, spec, 'param', '')));
            if ~isInput
                nameParam = char(string(getField(app, spec, 'nameParam', '')));
                if ~isempty(nameParam)
                    param = nameParam;
                end
            end

            runtimeParams = getRuntimeNodeParams(app, nodeId);
            if app.RuntimeModeUnlocked && isstruct(runtimeParams) && isfield(runtimeParams, param) && ...
                    isConfiguredBindingValue(app, runtimeParams.(param))
                value = bindingValueToDisplay(app, choiceScalarText(app, runtimeParams.(param)), node, spec);
                return;
            end

            p = getField(app, node, 'params', struct());
            % Keep inactive symbolic values visible. Hiding one behind an
            % empty control must not suggest that the stored binding is valid.
            if isstruct(p) && isfield(p, param) && (~isInput || isConfiguredBindingValue(app, p.(param)))
                value = bindingValueToDisplay(app, choiceScalarText(app, p.(param)), node, spec);
                return;
            end

            if isInput && isstruct(runtimeParams) && isfield(runtimeParams, param) && ...
                    isConfiguredBindingValue(app, runtimeParams.(param))
                value = bindingValueToDisplay(app, choiceScalarText(app, runtimeParams.(param)), node, spec);
                return;
            end

            if isInput && isempty(value) && isAllChannelBindingSpec(app, spec)
                value = '<all>';
                return;
            end

            if isInput && isempty(value) && strcmpi(char(string(getField(app, spec, 'type', ''))), 'dataSeriesVariable')
                value = 'auto';
                return;
            end

            if ~isInput && isempty(value)
                value = char(string(getField(app, node, 'id', '')));
            end
        end

        function tf = symbolicBindingIsActive(app, value)
            tf = true;
            sourceNode = symbolicBindingSourceNode(app, choiceScalarText(app, value));
            if isempty(sourceNode)
                return;
            end
            tf = isRunNodeActive(app, sourceNode);
        end

        function displayValue = bindingValueToDisplay(app, value, node, spec)
            displayValue = strtrim(char(string(value)));
            variableSuffix = '';
            if strcmpi(char(string(getField(app, spec, 'type', ''))), 'dataSeriesVariable')
                variableSuffix = dataSeriesVariableNameFromBindingForUi(app, displayValue);
            end
            if isAllChannelBindingSpec(app, spec) && isAllChannelSelectorText(app, displayValue)
                displayValue = '<all>';
                return;
            end
            if ~startsWith(displayValue, '@')
                return;
            end

            sourceNode = symbolicBindingSourceNode(app, displayValue);
            if ~isempty(sourceNode)
                requestedResource = symbolicBindingResourceKey(app, displayValue);
                available = upstreamCompatibleResources(app, node, spec);
                for i = 1:numel(available)
                    if strcmp(char(string(getField(app, available(i), 'sourceNode', ''))), sourceNode)
                        if ~isempty(requestedResource)
                            role = char(string(getField(app, available(i), 'role', '')));
                            symbol = char(string(getField(app, available(i), 'symbol', '')));
                            if ~(strcmpi(role, requestedResource) || strcmpi(symbol, requestedResource) || endsWith(symbol, ['.' requestedResource], 'IgnoreCase', true))
                                continue;
                            end
                        end
                        label = resourceChoiceLabel(app, available(i), spec);
                        if ~isempty(label)
                            if ~isempty(variableSuffix) && startsWith(label, '<') && endsWith(label, '>')
                                label = [label(1:end-1) ' / ' variableSuffix '>'];
                            end
                            displayValue = label;
                            return;
                        end
                    end
                end
                inactiveLabel = inactiveSymbolicBindingLabel(app, displayValue, node, spec);
                if ~isempty(inactiveLabel)
                    if ~isempty(variableSuffix) && startsWith(inactiveLabel, '<') && endsWith(inactiveLabel, '>')
                        inactiveLabel = [inactiveLabel(1:end-1) ' / ' variableSuffix '>'];
                    end
                    displayValue = inactiveLabel;
                    return;
                end
            end

            displayValue = ['<' displayValue(2:end) '>'];
        end

        function label = inactiveSymbolicBindingLabel(app, value, node, spec)
            label = '';
            sourceNode = symbolicBindingSourceNode(app, value);
            if isempty(sourceNode)
                return;
            end
            activeIds = selectedRunNodeIds(app);
            if isempty(activeIds) || any(strcmp(activeIds, sourceNode))
                return;
            end
            ids = {};
            if ~isempty(app.Data.nodes)
                ids = cellstr(string({app.Data.nodes.id}));
            end
            srcIdx = find(strcmp(ids, sourceNode), 1);
            if isempty(srcIdx)
                return;
            end
            srcNode = app.Data.nodes(srcIdx);
            role = char(string(getField(app, spec, 'role', 'resource')));
            concrete = '';
            try
                requestedResource = symbolicBindingResourceKey(app, value);
                contract = pipelineNodeContract(srcNode);
                outs = getField(app, getField(app, contract, 'resources', struct()), 'out', struct([]));
                wantedType = lower(char(string(getField(app, spec, 'type', ''))));
                wantedRole = lower(char(string(getField(app, spec, 'role', ''))));
                for i = 1:numel(outs)
                    outType = lower(char(string(getField(app, outs(i), 'type', ''))));
                    outRole = lower(char(string(getField(app, outs(i), 'role', ''))));
                    if resourceSpecCompatibleForUi(app, wantedType, wantedRole, outType, outRole)
                        outSymbol = char(string(getField(app, outs(i), 'symbol', '')));
                        if ~isempty(requestedResource) && ...
                                ~(strcmpi(outRole, requestedResource) || strcmpi(outSymbol, requestedResource) || endsWith(outSymbol, ['.' requestedResource], 'IgnoreCase', true))
                            continue;
                        end
                        concrete = outputBindingNameForNode(app, srcNode, outs(i));
                        role = char(string(getField(app, outs(i), 'role', role)));
                        break;
                    end
                end
            catch
            end
            if isempty(concrete)
                label = ['<' role ' output from ' sourceNode ' (inactive)>'];
            else
                label = ['<' role ' output from ' sourceNode ' / ' concrete ' (inactive)>'];
            end
        end

        function choices = bindingInputChoices(app, node, spec, currentValue)
            choices = {};
            currentValue = choiceScalarText(app, currentValue);

            inputReport = currentResourceInputReport(app, node, spec);
            available = struct([]);
            if isstruct(inputReport)
                if isfield(inputReport, 'available') && ~isempty(inputReport.available)
                    available = inputReport.available;
                elseif isfield(inputReport, 'autoChoice') && ~isempty(inputReport.autoChoice)
                    available = inputReport.autoChoice;
                end
            end
            if isempty(available)
                available = upstreamCompatibleResources(app, node, spec);
            end
            runtimeChoices = runtimeBindingChoices(app, spec);
            upstreamChoices = {};

            for i = 1:numel(available)
                if resourceChoiceIsAmbiguousForSpec(app, available(i), spec)
                    continue;
                end
                label = resourceChoiceLabel(app, available(i), spec);
                sourceKind = lower(char(string(getField(app, available(i), 'sourceKind', ''))));
                if ~isempty(label)
                    if any(strcmp(sourceKind, {'context','ctx','runtime'}))
                        runtimeChoices{end+1} = label; %#ok<AGROW>
                    else
                        upstreamChoices{end+1} = label; %#ok<AGROW>
                    end
                end
                concrete = char(string(getField(app, available(i), 'concreteName', '')));
                if ~isempty(concrete) && any(strcmp(sourceKind, {'context','ctx','runtime'}))
                    runtimeChoices{end+1} = concrete; %#ok<AGROW>
                end
            end

            if isAllChannelBindingSpec(app, spec)
                runtimeChoices = [{'<all>'} runtimeChoices]; %#ok<AGROW>
            end
            graphChoices = graphResourceChoiceLabels(app, node, spec);
            if parsedChannelInventoryIsAuthoritative(app, spec, runtimeChoices)
                % In raw-parser mode, concrete source/ROI image names come
                % exclusively from the parser inventory. Generic graph
                % placeholders such as Channel1 must not masquerade as
                % valid alternatives to the parsed channel names.  Derived
                % outputs are different: they are explicit graph resources,
                % not guessed raw channels, and must remain selectable.
                % Otherwise a parsed raw inventory hides a preceding
                % combineMultipleChannels output from a CNN/LSTM binding.
                choices = [runtimeChoices graphChoices]; %#ok<AGROW>
            else
                choices = [upstreamChoices runtimeChoices graphChoices]; %#ok<AGROW>
            end

            if isempty(choices) && ~isempty(currentValue)
                choices{end+1} = currentValue; %#ok<AGROW>
            end

            if isempty(choices)
                role = char(string(getField(app, spec, 'role', 'resource')));
                choices = {['<' role ' output>']};
            end
            choices = unique(choices(~cellfun(@isempty, choices)), 'stable');
        end

        function tf = parsedChannelInventoryIsAuthoritative(app, spec, runtimeChoices)
            tf = false;
            if isempty(runtimeChoices) || runtimeStartsFromExistingProject(app) || ...
                    runtimeStartsFromClassifier(app)
                return;
            end
            type = lower(char(string(getField(app, spec, 'type', ''))));
            role = lower(char(string(getField(app, spec, 'role', ''))));
            tf = strcmp(type, 'channel') && ...
                any(strcmp(role, {'source','roi_image','score_roi_image', ...
                'derived_roi_image','z_stack'})) && ...
                isfield(app.RuntimeParseInfo, 'channels') && ...
                ~isempty(app.RuntimeParseInfo.channels);
        end

        function choices = appendDataSeriesVariableSuffixToSymbolicChoices(app, choices, currentValue)
            variableName = dataSeriesVariableNameFromBindingForUi(app, currentValue);
            if isempty(variableName) || isempty(choices)
                return;
            end
            for i = 1:numel(choices)
                txt = strtrim(char(string(choices{i})));
                if ~(startsWith(txt, '<') && endsWith(txt, '>') && contains(txt, ' output from '))
                    continue;
                end
                inner = txt(2:end-1);
                if contains(inner, [' / ' variableName])
                    continue;
                end
                choices{i} = [txt(1:end-1) ' / ' variableName '>'];
            end
        end

        function variableName = dataSeriesVariableNameFromBindingForUi(app, value) %#ok<INUSD>
            variableName = '';
            value = strtrim(char(string(value)));
            if isempty(value)
                return;
            end
            parts = regexp(value, '\s*/\s*', 'split');
            if numel(parts) >= 2
                variableName = strtrim(strjoin(parts(2:end), ' / '));
            elseif ~startsWith(value, '@')
                variableName = value;
            end
        end

        function seriesName = dataSeriesNameFromVariableBindingForUi(app, value) %#ok<INUSD>
            seriesName = strtrim(char(string(value)));
            if contains(seriesName, '/')
                parts = regexp(seriesName, '\s*/\s*', 'split');
                if ~isempty(parts)
                    seriesName = strtrim(parts{1});
                end
            end
        end

        function tf = isAllChannelBindingSpec(app, spec) %#ok<INUSD>
            specType = lower(char(string(getField(app, spec, 'type', ''))));
            specRole = lower(char(string(getField(app, spec, 'role', ''))));
            specParam = lower(char(string(getField(app, spec, 'param', ''))));
            tf = strcmp(specType, 'channel') && ...
                (strcmp(specParam, 'channels') || strcmp(specParam, 'zstackchannelnames')) && ...
                any(strcmp(specRole, {'source','roi_image','z_stack'}));
        end

        function tf = isSingularChannelBindingSpec(app, spec)
            tf = false;
            specType = lower(char(string(getField(app, spec, 'type', ''))));
            specParam = lower(char(string(getField(app, spec, 'param', ''))));
            if ~strcmp(specType, 'channel') || isAllChannelBindingSpec(app, spec)
                return;
            end

            required = logical(getField(app, spec, 'required', false));
            singularParams = {'channel','inputchannelname','instancechannelname'};
            tf = required || any(strcmp(specParam, singularParams)) || ...
                ~isempty(regexp(specParam, '^mask\d+_name$', 'once')) || ...
                ~isempty(regexp(specParam, '^channel\d+_name$', 'once'));
        end

        function tf = resourceChoiceIsAmbiguousForSpec(app, resource, spec)
            tf = false;
            if ~isSingularChannelBindingSpec(app, spec)
                return;
            end

            sourceKind = lower(char(string(getField(app, resource, 'sourceKind', ''))));
            if any(strcmp(sourceKind, {'context','ctx','runtime'}))
                return;
            end

            sourceNode = lower(char(string(getField(app, resource, 'sourceNode', ''))));
            sourcePort = lower(char(string(getField(app, resource, 'sourcePort', ''))));
            symbol = lower(char(string(getField(app, resource, 'symbol', ''))));
            concrete = lower(strtrim(char(string(getField(app, resource, 'concreteName', '')))));
            nodeIds = {};
            try
                if isfield(app.Data, 'nodes') && ~isempty(app.Data.nodes)
                    nodeIds = lower(cellstr(string({app.Data.nodes.id})));
                end
            catch
                nodeIds = {};
            end

            hasSingleConcrete = ~isempty(concrete) && ...
                ~startsWith(concrete, '@') && ...
                ~any(strcmp(concrete, {'channels','all','*',':',sourceNode})) && ...
                ~any(strcmp(concrete, nodeIds));
            if hasSingleConcrete
                return;
            end

            tf = any(strcmp(sourceKind, {'sourceinventory','imagestoroi'})) || ...
                strcmp(sourcePort, 'channels') || ...
                endsWith(symbol, '.channels');
        end

        function tf = isAllChannelBindingLabel(app, value) %#ok<INUSD>
            tf = strcmpi(strtrim(char(string(value))), '<all>');
        end

        function tf = isAllChannelSelectorText(app, value) %#ok<INUSD>
            value = lower(strtrim(char(string(value))));
            tf = any(strcmp(value, {'all','*',':','<all>','@source','@all_channels'}));
        end

        function choices = runtimeBindingChoices(app, spec)
            choices = {};
            type = lower(char(string(getField(app, spec, 'type', ''))));
            role = lower(char(string(getField(app, spec, 'role', ''))));
            if strcmp(type, 'channel') && strcmp(role, 'source')
                choices = runtimeSourceChannels(app);
            elseif strcmp(type, 'channel') && any(strcmp(role, {'roi_image','score_roi_image','derived_roi_image','z_stack'}))
                choices = runtimeValidationRoiChannels(app);
            elseif strcmp(type, 'channel') && strcmp(role, 'mask_roi_image')
                choices = runtimeMaskChoices(app);
                if isempty(choices)
                    choices = runtimeValidationRoiChannels(app);
                end
            elseif strcmp(type, 'mask')
                choices = runtimeMaskChoices(app);
            elseif strcmp(type, 'dataseries')
                choices = runtimeDataSeriesChoices(app, role);
            elseif strcmp(type, 'dataseriesvariable')
                choices = runtimeDataSeriesVariableChoices(app);
            end
        end

        function channels = runtimeConcreteChannels(app)
            channels = {};
            sourceChannels = runtimeSourceChannels(app);
            if ~isempty(sourceChannels)
                channels = [channels sourceChannels]; %#ok<AGROW>
            end
            roiChannels = runtimeValidationRoiChannels(app);
            if ~isempty(roiChannels)
                channels = [channels roiChannels]; %#ok<AGROW>
            end
            skip = startsWith(lower(string(channels)), 'resolved after') | strcmpi(string(channels), 'all') | strcmpi(string(channels), 'auto');
            channels = channels(~skip);
            channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
        end

        function channels = runtimeSourceChannels(app)
            channels = runtimeParsedChannelsOnly(app);
        end

        function channels = runtimeValidationRoiChannels(app)
            channels = {};
            if runtimeStartsFromClassifier(app)
                channels = explicitRuntimeRoiChannels(app);
                if isempty(channels)
                    channels = runtimeSourceChannels(app);
                end
            elseif runtimeStartsFromExistingProject(app)
                channels = runtimeRoiDisplayChannels(app);
                if isempty(channels)
                    channels = runtimeSourceChannels(app);
                end
            else
                channels = runtimeSourceChannels(app);
            end
            skip = startsWith(lower(string(channels)), 'resolved after') | strcmpi(string(channels), 'all') | strcmpi(string(channels), 'auto');
            channels = channels(~skip);
            channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
        end

        function channels = runtimeParsedChannelsOnly(app)
            channels = {};
            try
                if isfield(app.RuntimeParseInfo, 'channels') && ~isempty(app.RuntimeParseInfo.channels)
                    channels = cellstr(string(app.RuntimeParseInfo.channels(:)'));
                end
                skip = startsWith(lower(string(channels)), 'resolved after') | strcmpi(string(channels), 'all') | strcmpi(string(channels), 'auto');
                channels = channels(~skip);
                channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
            catch
                channels = {};
            end
        end

        function channels = runtimeRoiDisplayChannels(app)
            channels = {};
            if ~runtimeStartsFromExistingProject(app)
                return;
            end
            try
                [roiList, ~] = runtimeSampledRoisForDataSeries(app);
                if isempty(roiList)
                    return;
                end
                maxRoi = min(numel(roiList), 12);
                for r = 1:maxRoi
                    roiObj = roiList(r);
                    if isprop(roiObj, 'display') && ~isempty(roiObj.display) && ...
                            isfield(roiObj.display, 'channel') && ~isempty(roiObj.display.channel)
                        channels = [channels cellstr(string(roiObj.display.channel(:)'))]; %#ok<AGROW>
                    end
                    channels = [channels roiStoredChannelNames(app, roiObj)]; %#ok<AGROW>
                end
                if app.RuntimeInventoryMetadataOnly
                    channels = [channels savedRuntimeInventoryNames(app, 'roiChannels') ...
                        savedRuntimeInventoryNames(app, 'availableChannels')];
                end
                channels = unique(channels(~cellfun(@isempty, channels)), 'stable');
            catch
                channels = {};
            end
        end

        function masks = runtimeMaskChoices(app)
            masks = {};
            try
                channels = runtimeConcreteChannels(app);
                if isempty(channels)
                    return;
                end
                low = lower(string(channels));
                keep = contains(low, "mask") | contains(low, "seg") | contains(low, "cellpose") | ...
                    contains(low, "sam") | contains(low, "viterbi") | contains(low, "track") | contains(low, "result");
                masks = cellstr(string(channels(keep)));
                masks = unique(masks(~cellfun(@isempty, masks)), 'stable');
            catch
                masks = {};
            end
        end

        function choices = runtimeDataSeriesChoices(app, role)
            choices = {};
            try
                names = runtimeDataSeriesNames(app);
                if isempty(names)
                    return;
                end
                if strcmpi(role, 'classification')
                    low = lower(string(names));
                    preferred = contains(low, "div") | contains(low, "class") | contains(low, "cnn") | ...
                        contains(low, "lstm") | contains(low, "foci") | contains(low, "presence");
                    names = [names(preferred) names(~preferred)];
                elseif strcmpi(role, 'metrics')
                    keep = contains(lower(string(names)), "quant") | contains(lower(string(names)), "metric");
                    if any(keep)
                        names = [names(keep) names(~keep)];
                    end
                end
                choices = unique(names(~cellfun(@isempty, names)), 'stable');
            catch
                choices = {};
            end
        end

        function names = runtimeDataSeriesNames(app)
            names = {};
            if app.RuntimeInventoryMetadataOnly
                names = savedRuntimeInventoryNames(app, 'dataSeriesNames');
                names = [names savedRuntimeInventoryNames(app, 'dataSeries')];
            end
            try
                cacheKey = runtimeDataSeriesCacheKey(app);
                if isfield(app.RuntimeDataSeriesCache, 'key') && strcmp(char(string(app.RuntimeDataSeriesCache.key)), cacheKey) && ...
                        isfield(app.RuntimeDataSeriesCache, 'names')
                    names = app.RuntimeDataSeriesCache.names;
                    return;
                end
                [roiList, nFovSampled] = runtimeSampledRoisForDataSeries(app);
                if isempty(roiList)
                    names = unique(names, 'stable');
                    app.RuntimeDataSeriesCache = struct('key', cacheKey, 'names', {names}, 'sampledRoiCount', 0, 'sampledFovCount', 0);
                    return;
                end
                maxRoi = min(numel(roiList), 12);
                for r = 1:maxRoi
                    roiObj = roiList(r);
                    try
                        if ~isprop(roiObj, 'path') || isempty(roiObj.path)
                            continue;
                        end
                    catch
                        continue;
                    end
                    try
                        loadRuntimeRoiData(app, roiObj);
                    catch
                    end
                    if ~isprop(roiObj, 'data') || isempty(roiObj.data)
                        continue;
                    end
                    ds = roiObj.data;
                    for i = 1:numel(ds)
                        displayName = dataSeriesDisplayName(app, ds(i));
                        if ~isempty(displayName)
                            names{end+1} = displayName; %#ok<AGROW>
                        end
                    end
                    if numel(unique(names(~cellfun(@isempty, names)), 'stable')) >= 16
                        break;
                    end
                end
                names = unique(names(~cellfun(@isempty, names)), 'stable');
                app.RuntimeDataSeriesCache = struct('key', cacheKey, 'names', {names}, ...
                    'sampledRoiCount', min(maxRoi, numel(roiList)), ...
                    'sampledFovCount', nFovSampled);
            catch
                names = {};
            end
        end

        function names = runtimeCachedDataSeriesNames(app)
            names = {};
            try
                cacheKey = runtimeDataSeriesCacheKey(app);
                if isfield(app.RuntimeDataSeriesCache, 'key') && strcmp(char(string(app.RuntimeDataSeriesCache.key)), cacheKey) && ...
                        isfield(app.RuntimeDataSeriesCache, 'names')
                    names = app.RuntimeDataSeriesCache.names;
                end
                if isempty(names)
                    names = runtimeDataSeriesNames(app);
                end
            catch
                names = {};
            end
        end

        function names = runtimeDataSeriesVariableNames(app, dataSeriesSelector)
            names = {};
            selectorText = strtrim(char(string(dataSeriesSelector)));
            try
                [roiList, ~] = runtimeSampledRoisForDataSeries(app);
                if isempty(roiList)
                    return;
                end
                maxRoi = min(numel(roiList), 12);
                for r = 1:maxRoi
                    roiObj = roiList(r);
                    try
                        loadRuntimeRoiData(app, roiObj);
                    catch
                    end
                    if ~isprop(roiObj, 'data') || isempty(roiObj.data)
                        continue;
                    end
                    ds = roiObj.data;
                    for i = 1:numel(ds)
                        dsName = dataSeriesDisplayName(app, ds(i));
                        if ~dataSeriesSelectorMatches(app, dsName, selectorText)
                            continue;
                        end
                        try
                            tbl = ds(i).data;
                        catch
                            tbl = [];
                        end
                        if ~istable(tbl) || isempty(tbl.Properties.VariableNames)
                            continue;
                        end
                        vars = tbl.Properties.VariableNames;
                        names = [names vars]; %#ok<AGROW>
                    end
                    names = unique(names(~cellfun(@isempty, names)), 'stable');
                    if ~isempty(names)
                        return;
                    end
                end
            catch
                names = {};
            end
        end

        function tf = dataSeriesSelectorMatches(app, dsName, selectorText) %#ok<INUSD>
            dsName = strtrim(char(string(dsName)));
            selectorText = strtrim(char(string(selectorText)));
            if isempty(dsName)
                tf = false;
                return;
            end
            if isempty(selectorText) || any(strcmp(selectorText, {'<unconfigured>','<unresolved>'}))
                tf = true;
                return;
            end
            tf = strcmp(dsName, selectorText) || contains(selectorText, ['/' dsName]) || contains(selectorText, [' ' dsName]) || contains(selectorText, dsName);
        end

        function choices = runtimeDataSeriesVariableChoices(app)
            choices = {'auto'};
            try
                [roiList, ~] = runtimeSampledRoisForDataSeries(app);
                if isempty(roiList)
                    return;
                end
                maxRoi = min(numel(roiList), 12);
                for r = 1:maxRoi
                    roiObj = roiList(r);
                    try
                        loadRuntimeRoiData(app, roiObj);
                    catch
                    end
                    if ~isprop(roiObj, 'data') || isempty(roiObj.data)
                        continue;
                    end
                    ds = roiObj.data;
                    for i = 1:numel(ds)
                        dsName = dataSeriesDisplayName(app, ds(i));
                        if isempty(dsName)
                            continue;
                        end
                        try
                            tbl = ds(i).data;
                        catch
                            tbl = [];
                        end
                        if ~istable(tbl) || isempty(tbl.Properties.VariableNames)
                            continue;
                        end
                        vars = tbl.Properties.VariableNames;
                        for k = 1:numel(vars)
                            choices{end+1} = [dsName ' / ' vars{k}]; %#ok<AGROW>
                        end
                    end
                    choices = unique(choices(~cellfun(@isempty, choices)), 'stable');
                    if numel(choices) > 1
                        return;
                    end
                end
            catch
                choices = {'auto'};
            end
        end

        function clearRuntimeDataSeriesCache(app)
            app.RuntimeDataSeriesCache = struct('key', '', 'names', {{}}, 'sampledRoiCount', 0, 'sampledFovCount', 0);
            app.NumericTraceInventory=[];
            app.RuntimeLoadedRoiData = {};
            app.RuntimeStoredChannelsCache = [];
        end

        function names = savedRuntimeInventoryNames(app, key)
            names = {};
            ctx = app.SavedRuntimeInventoryContext;
            sources = {ctx, getField(app, ctx, 'run', struct())};
            for i = 1:numel(sources)
                value = getField(app, sources{i}, key, []);
                if ischar(value) || isstring(value) || iscellstr(value)
                    names = [names cellstr(string(value(:)'))]; %#ok<AGROW>
                end
            end
            names = unique(names(~cellfun(@isempty, names)), 'stable');
        end

        function loadRuntimeRoiData(app, roiObj)
            if app.RuntimeInventoryMetadataOnly, return; end
            key = fullfile(char(string(roiObj.path)), ['data_' char(string(roiObj.id)) '.mat']);
            if any(strcmp(app.RuntimeLoadedRoiData, key)), return; end
            roiObj.load('data', 'silent');
            app.RuntimeLoadedRoiData{end+1} = key;
        end

        function discoverRuntimeRoiInventory(app, force)
            if nargin >= 2 && force
                app.RuntimeInventoryMetadataOnly = true;
            end
            if app.OpeningPipeline || ~app.RuntimeInventoryMetadataOnly, return; end
            d = openRuntimeProgress(app, 'Input inventory', 'Loading available ROI channels and dataseries...');
            cleanup = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
            app.RuntimeInventoryMetadataOnly = false;
            clearRuntimeDataSeriesCache(app);
            runtimeConcreteChannels(app);
            runtimeDataSeriesNames(app);
            updateRuntimeResourceInventory(app);
        end

        function refreshInputInventory(app)
            app.RuntimeInventoryMetadataOnly = true;
            discoverRuntimeRoiInventory(app);
            refreshValidationReport(app, false);
            refreshModuleTabs(app);
            redrawGraph(app);
        end

        function choices = currentBindingChoices(app, node, param)
            rows = bindingTableData(app, node);
            idx = find(strcmp(rows(:,3), param), 1);
            choices = {};
            if ~isempty(idx), choices = flattenChoiceList(app, rows{idx,5}); end
        end

        function updateDiscoveredDropdown(app, ctrl, choices) %#ok<INUSD>
            % Discovery updates alternatives only. Opening a list must not
            % select another value or write back to the saved configuration.
            value = char(string(ctrl.Value));
            choices = unique([{value} choices(:)'], 'stable');
            ctrl.ItemsData = {};
            ctrl.Items = choices;
            ctrl.Value = value;
        end

        function loadBindingChoicesOnDemand(app, ctrl, node, param)
            discoverRuntimeRoiInventory(app);
            updateDiscoveredDropdown(app, ctrl, currentBindingChoices(app, node, param));
        end

        function loadSeriesChoicesOnDemand(app, ctrl, node, param)
            discoverRuntimeRoiInventory(app);
            choices = currentBindingChoices(app, node, param);
            choices = dataSeriesVariableSeriesChoicesForUi(app, choices, ctrl.Value);
            if strcmpi(node.pkg, 'lstm_timeseries')
                inventory = numericTraceInventory(app);
                choices = [choices {inventory.group}];
            end
            updateDiscoveredDropdown(app, ctrl, choices);
        end

        function loadVariableChoicesOnDemand(app, ctrl, seriesDrop)
            discoverRuntimeRoiInventory(app);
            choices = dataSeriesVariableDropdownChoicesForUi(app, seriesDrop.Value, ctrl.Value);
            updateDiscoveredDropdown(app, ctrl, choices);
        end

        function loadNumericComponentsOnDemand(app, ctrl, selector)
            discoverRuntimeRoiInventory(app);
            columns = numericBindingColumns(app, selector);
            value = ctrl.Value;
            items = unique([1:max(1, columns) value], 'stable');
            ctrl.ItemsData = {};
            ctrl.Items = arrayfun(@(i)sprintf('Component %d',i),items,'UniformOutput',false);
            ctrl.ItemsData = items;
            ctrl.Value = value;
        end

        function name = dataSeriesDisplayName(app, item) %#ok<INUSD>
            name = '';
            probes = {'groupid','name','id','concreteName','symbol'};
            for i = 1:numel(probes)
                key = probes{i};
                value = [];
                try
                    if isstruct(item) && isfield(item, key)
                        value = item.(key);
                    elseif isobject(item) && isprop(item, key)
                        value = item.(key);
                    end
                catch
                    value = [];
                end
                if isempty(value)
                    continue;
                end
                txt = strtrim(char(string(value)));
                if ~isempty(txt)
                    name = txt;
                    return;
                end
            end
        end

        function key = runtimeDataSeriesCacheKey(app)
            parts = {sprintf('metadataOnly=%d', app.RuntimeInventoryMetadataOnly)};
            if runtimeStartsFromClassifier(app) && ~isempty(app.ExplicitRuntimeRoiList)
                rois = app.ExplicitRuntimeRoiList;
                parts{end+1} = ['classifierROI=' fullfile(char(string(rois(1).path)), char(string(rois(1).id)))];
                parts{end+1} = sprintf('classifierRoiCount=%d', numel(rois));
            end
            try, parts{end+1} = char(string(app.CurrentProjectVarName)); catch, end %#ok<AGROW>
            try
                if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                    [pth, file] = app.CurrentProject.getPath;
                    parts{end+1} = fullfile(pth, [file '.mat']); %#ok<AGROW>
                    parts{end+1} = sprintf('nfov=%d', numel(app.CurrentProject.fov)); %#ok<AGROW>
                    if ~isempty(app.CurrentProject.fov)
                        parts{end+1} = sprintf('nroi1=%d', numel(app.CurrentProject.fov(1).roi)); %#ok<AGROW>
                    end
                end
            catch
            end
            try, parts{end+1} = ['fovs=' char(string(getRuntimeValue(app, 'fovs')))]; catch, end %#ok<AGROW>
            try, parts{end+1} = ['rois=' char(string(getRuntimeValue(app, 'rois')))]; catch, end %#ok<AGROW>
            key = strjoin(parts, '|');
        end

        function roiList = runtimeSelectedRois(app)
            roiList = [];
            if runtimeStartsFromClassifier(app)
                [roiList, ~] = selectedClassifierRoisForValidation(app);
                return;
            end
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            try
                fovIdx = parseRuntimeFovSelection(app, getRuntimeValue(app, 'fovs'));
                if isempty(fovIdx)
                    fovIdx = 1:numel(app.CurrentProject.fov);
                end
                fovIdx = fovIdx(fovIdx >= 1 & fovIdx <= numel(app.CurrentProject.fov));
                if isempty(fovIdx)
                    return;
                end
                roiSel = parseLooseSelection(app, getRuntimeValue(app, 'rois'));
                f = app.CurrentProject.fov(fovIdx(1));
                if isempty(f.roi)
                    return;
                end
                if isempty(roiSel)
                    roiIdx = 1:numel(f.roi);
                elseif isnumeric(roiSel)
                    roiIdx = round(double(roiSel(:)'));
                    roiIdx = roiIdx(roiIdx >= 1 & roiIdx <= numel(f.roi));
                else
                    roiIdx = 1:numel(f.roi);
                end
                if isempty(roiIdx)
                    return;
                end
                roiList = f.roi(roiIdx);
            catch
                roiList = [];
            end
        end

        function [roiList, nFovSampled] = runtimeSampledRoisForDataSeries(app)
            roiList = [];
            nFovSampled = 0;
            if runtimeStartsFromClassifier(app)
                roiList = runtimeSelectedRois(app);
                indices = deterministicSample(app, 1:numel(roiList), ...
                    min(12, numel(roiList)), runtimeDataSeriesCacheKey(app));
                roiList = roiList(indices);
                return;
            end
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            try
                fovIdx = parseRuntimeFovSelection(app, getRuntimeValue(app, 'fovs'));
                if isempty(fovIdx)
                    fovIdx = 1:numel(app.CurrentProject.fov);
                end
                fovIdx = fovIdx(fovIdx >= 1 & fovIdx <= numel(app.CurrentProject.fov));
                if isempty(fovIdx)
                    return;
                end

                maxFov = min(numel(fovIdx), 4);
                fovIdx = deterministicSample(app, fovIdx, maxFov, runtimeDataSeriesCacheKey(app));
                roiSel = parseLooseSelection(app, getRuntimeValue(app, 'rois'));

                for k = 1:numel(fovIdx)
                    f = app.CurrentProject.fov(fovIdx(k));
                    if isempty(f.roi)
                        continue;
                    end
                    if isempty(roiSel) || ~isnumeric(roiSel)
                        roiIdx = 1:numel(f.roi);
                    else
                        roiIdx = round(double(roiSel(:)'));
                        roiIdx = roiIdx(roiIdx >= 1 & roiIdx <= numel(f.roi));
                    end
                    if isempty(roiIdx)
                        continue;
                    end
                    roiIdx = deterministicSample(app, roiIdx, min(numel(roiIdx), 3), ...
                        [runtimeDataSeriesCacheKey(app) '|fov=' num2str(fovIdx(k))]);
                    roiList = [roiList f.roi(roiIdx)]; %#ok<AGROW>
                    nFovSampled = nFovSampled + 1;
                    if numel(roiList) >= 12
                        roiList = roiList(1:12);
                        return;
                    end
                end
            catch
                roiList = [];
                nFovSampled = 0;
            end
        end

        function sample = deterministicSample(app, values, n, salt) %#ok<INUSD>
            values = values(:)';
            if numel(values) <= n
                sample = values;
                return;
            end
            seed = sum(double(char(string(salt)))) + 7919 * numel(values) + 104729 * n;
            oldState = rng;
            cleanupObj = onCleanup(@()rng(oldState)); %#ok<NASGU>
            rng(mod(seed, 2^32), 'twister');
            order = randperm(numel(values), n);
            sample = values(sort(order));
        end

        function labels = graphResourceChoiceLabels(app, node, spec)
            labels = {};
            nodeId = char(string(getField(app, node, 'id', '')));
            wantedType = lower(char(string(getField(app, spec, 'type', ''))));
            wantedRole = lower(char(string(getField(app, spec, 'role', ''))));
            if isempty(nodeId) || isempty(app.Data.nodes)
                return;
            end
            for i = 1:numel(app.Data.nodes)
                srcNode = app.Data.nodes(i);
                sourceNode = char(string(getField(app, srcNode, 'id', '')));
                if isempty(sourceNode) || strcmp(sourceNode, nodeId)
                    continue;
                end
                if ~isRunNodeActive(app, sourceNode)
                    continue;
                end
                if isfield(srcNode, 'contract')
                    srcNode = rmfield(srcNode, 'contract');
                end
                try
                    contract = pipelineNodeContract(srcNode);
                catch
                    continue;
                end
                resources = getField(app, contract, 'resources', struct());
                outs = getField(app, resources, 'out', struct([]));
                for j = 1:numel(outs)
                    outSpec = outs(j);
                    outType = lower(char(string(getField(app, outSpec, 'type', ''))));
                    outRole = lower(char(string(getField(app, outSpec, 'role', ''))));
                    if ~resourceSpecCompatibleForUi(app, wantedType, wantedRole, outType, outRole)
                        continue;
                    end
                    resource = makeUiResourceChoice(app, srcNode, outSpec);
                    if resourceChoiceIsAmbiguousForSpec(app, resource, spec)
                        continue;
                    end
                    concrete = outputBindingNameForNode(app, srcNode, outSpec);
                    role = char(string(getField(app, outSpec, 'role', wantedRole)));
                    if isempty(concrete)
                        labels{end+1} = ['<' role ' output from ' sourceNode '>']; %#ok<AGROW>
                    else
                        labels{end+1} = ['<' role ' output from ' sourceNode ' / ' concrete '>']; %#ok<AGROW>
                    end
                end
            end
            labels = unique(labels(~cellfun(@isempty, labels)), 'stable');
        end

        function name = outputBindingNameForNode(app, node, spec)
            name = '';
            params = getField(app, node, 'params', struct());
            nameParam = char(string(getField(app, spec, 'nameParam', '')));
            if ~isempty(nameParam) && isstruct(params) && isfield(params, nameParam) && ~isempty(params.(nameParam))
                name = choiceScalarText(app, params.(nameParam));
                if resourceOutputNameIsConcrete(app, spec, name)
                    name = normalizeUiPhysicalResourceOutputName(app, node, spec, name);
                    return;
                end
                name = '';
            end
            param = char(string(getField(app, spec, 'param', '')));
            if ~isempty(param) && isstruct(params) && isfield(params, param) && ~isempty(params.(param))
                name = choiceScalarText(app, params.(param));
                if resourceOutputNameIsConcrete(app, spec, name)
                    name = normalizeUiPhysicalResourceOutputName(app, node, spec, name);
                    return;
                end
                name = '';
            end
            role = lower(char(string(getField(app, spec, 'role', ''))));
            if strcmp(role, 'roi_image')
                name = '';
            else
                name = defaultConcreteOutputName(app, node, spec);
            end
            name = normalizeUiPhysicalResourceOutputName(app, node, spec, name);
        end

        function name = defaultConcreteOutputName(app, node, spec)
            name = char(string(getField(app, spec, 'concreteName', '')));
            if resourceOutputNameIsConcrete(app, spec, name)
                return;
            end
            name = char(string(getField(app, spec, 'symbol', '')));
            if ~isempty(name) && contains(name, '.')
                parts = regexp(name, '\.', 'split');
                name = parts{end};
            end
            if resourceOutputNameIsConcrete(app, spec, name)
                return;
            end
            name = char(string(getField(app, node, 'id', '')));
        end

        function name = normalizeUiPhysicalResourceOutputName(app, node, spec, name)
            name = pipelinePhysicalResourceOutputName(node, spec, name);
        end

        function tf = resourceOutputNameIsConcrete(app, spec, name) %#ok<INUSD>
            role = lower(char(string(getField(app, spec, 'role', ''))));
            name = strtrim(char(string(name)));
            tf = true;
            if strcmp(role, 'roi_image') && (isempty(name) || startsWith(name, '@') || any(strcmpi(name, {'all','*',':','<all>'})))
                tf = false;
            end
        end

        function resources = upstreamCompatibleResources(app, node, spec)
            resources = struct([]);
            nodeId = char(string(getField(app, node, 'id', '')));
            if isempty(nodeId) || isempty(app.Data.nodes)
                return;
            end

            sourceIds = {};
            edges = app.Data.edges;
            for i = 1:numel(edges)
                if strcmp(char(string(getField(app, edges(i), 'to', ''))), nodeId)
                    sourceIds{end+1} = char(string(getField(app, edges(i), 'from', ''))); %#ok<AGROW>
                end
            end
            ids = cellstr(string({app.Data.nodes.id}));
            idx = find(strcmp(ids, nodeId), 1);
            if ~isempty(idx) && idx > 1
                sourceIds = [sourceIds ids(1:idx-1)]; %#ok<AGROW>
            end
            sourceIds = unique(sourceIds(~cellfun(@isempty, sourceIds)), 'stable');

            wantedType = lower(char(string(getField(app, spec, 'type', ''))));
            wantedRole = lower(char(string(getField(app, spec, 'role', ''))));
            for i = 1:numel(sourceIds)
                if ~isRunNodeActive(app, sourceIds{i})
                    continue;
                end
                srcIdx = find(strcmp({app.Data.nodes.id}, sourceIds{i}), 1);
                if isempty(srcIdx)
                    continue;
                end
                srcNode = app.Data.nodes(srcIdx);
                if isfield(srcNode, 'contract')
                    srcNode = rmfield(srcNode, 'contract');
                end
                try
                    contract = pipelineNodeContract(srcNode);
                catch
                    continue;
                end
                outs = getField(app, getField(app, contract, 'resources', struct()), 'out', struct([]));
                for j = 1:numel(outs)
                    outSpec = outs(j);
                    outType = lower(char(string(getField(app, outSpec, 'type', ''))));
                    outRole = lower(char(string(getField(app, outSpec, 'role', ''))));
                    if ~resourceSpecCompatibleForUi(app, wantedType, wantedRole, outType, outRole)
                        continue;
                    end
                    resources = appendStruct(app, resources, makeUiResourceChoice(app, srcNode, outSpec)); %#ok<AGROW>
                end
            end
        end

        function tf = resourceSpecCompatibleForUi(app, wantedType, wantedRole, availableType, availableRole) %#ok<INUSD>
            wantedType = lower(char(string(wantedType)));
            wantedRole = lower(char(string(wantedRole)));
            availableType = lower(char(string(availableType)));
            availableRole = lower(char(string(availableRole)));
            tf = strcmp(wantedType, availableType) && resourceRolesCompatibleForUi(app, wantedRole, availableRole);
            if tf
                return;
            end
            tf = strcmp(wantedType, 'dataseriesvariable') && strcmp(availableType, 'dataseries') && ...
                dataSeriesVariableRoleCompatibleForUi(app, wantedRole, availableRole);
            if tf
                return;
            end
            tf = strcmp(wantedType, 'channel') && strcmp(wantedRole, 'mask_roi_image') && ...
                strcmp(availableType, 'mask') && strcmp(availableRole, 'segmentation');
        end

        function tf = dataSeriesVariableRoleCompatibleForUi(app, wantedRole, availableRole) %#ok<INUSD>
            wantedRole = lower(char(string(wantedRole)));
            availableRole = lower(char(string(availableRole)));
            tf = false;
            if isempty(wantedRole) || isempty(availableRole)
                tf = true;
                return;
            end
            switch wantedRole
                case 'numeric_trace'
                    tf = true;
                case {'metric_variable','metrics_variable'}
                    tf = strcmp(availableRole, 'metrics');
                case {'classification_label','classification_variable'}
                    tf = strcmp(availableRole, 'classification');
                otherwise
                    tf = strcmp(wantedRole, availableRole);
            end
        end

        function tf = resourceRolesCompatibleForUi(app, wantedRole, availableRole) %#ok<INUSD>
            wantedRole = lower(char(string(wantedRole)));
            availableRole = lower(char(string(availableRole)));
            tf = isempty(wantedRole) || isempty(availableRole) || strcmp(wantedRole, availableRole);
            if tf
                return;
            end
            if strcmp(wantedRole, 'score_roi_image')
                tf = any(strcmp(availableRole, roiScorableChannelRolesForUi(app)));
                return;
            end
            if strcmp(wantedRole, 'mask_roi_image')
                tf = any(strcmp(availableRole, {'roi_image','mask_roi_image','derived_roi_image','tracking','lineage_mask','lineage_cell_mask','lineage_mother_mask','lineage_bud_mask'}));
                return;
            end
            if strcmp(wantedRole, 'roi_image')
                tf = any(strcmp(availableRole, roiScorableChannelRolesForUi(app)));
                return;
            end
            if any(strcmp(wantedRole, {'brightfield_image', ...
                    'legacy_gfp_fluorescence','division_nucleus_fluorescence', ...
                    'bud_neck_fluorescence'}))
                tf = any(strcmp(availableRole, roiScorableChannelRolesForUi(app)));
                return;
            end
            tf = false;
        end

        function roles = roiScorableChannelRolesForUi(app) %#ok<INUSD>
            roles = {'roi_image','score_roi_image','derived_roi_image','probability','tracking','lineage_mask','lineage_cell_mask','lineage_confidence','lineage_mother_mask','lineage_bud_mask'};
        end

        function resource = makeUiResourceChoice(app, srcNode, outSpec)
            sourceNode = char(string(getField(app, srcNode, 'id', '')));
            sourcePort = char(string(getField(app, outSpec, 'port', '')));
            sourceKind = char(string(getField(app, outSpec, 'transfer', '')));
            concreteName = '';
            nameParam = char(string(getField(app, outSpec, 'nameParam', '')));
            params = getField(app, srcNode, 'params', struct());
            if ~isempty(nameParam) && isstruct(params) && isfield(params, nameParam) && ~isempty(params.(nameParam))
                concreteName = choiceScalarText(app, params.(nameParam));
                if ~resourceOutputNameIsConcrete(app, outSpec, concreteName)
                    concreteName = '';
                end
            end
            if isempty(concreteName)
                param = char(string(getField(app, outSpec, 'param', '')));
                if ~isempty(param) && isstruct(params) && isfield(params, param) && ~isempty(params.(param))
                    concreteName = choiceScalarText(app, params.(param));
                    if ~resourceOutputNameIsConcrete(app, outSpec, concreteName)
                        concreteName = '';
                    end
                end
            end
            if isempty(concreteName)
                role = lower(char(string(getField(app, outSpec, 'role', ''))));
                if ~strcmp(role, 'roi_image')
                    concreteName = defaultConcreteOutputName(app, srcNode, outSpec);
                end
            end
            symbol = char(string(getField(app, outSpec, 'symbol', '')));
            if isempty(symbol)
                symbol = sourcePort;
            end
            if ~contains(symbol, '.')
                symbol = [sourceNode '.' symbol];
            end
            concreteName = normalizeUiPhysicalResourceOutputName(app, srcNode, outSpec, concreteName);
            resource = struct( ...
                'type', char(string(getField(app, outSpec, 'type', ''))), ...
                'role', char(string(getField(app, outSpec, 'role', ''))), ...
                'symbol', symbol, ...
                'concreteName', concreteName, ...
                'sourceNode', sourceNode, ...
                'sourcePort', sourcePort, ...
                'sourceKind', sourceKind);
        end

        function tf = isRunNodeActive(app, nodeId)
            tf = true;
            selectedIds = selectedRunNodeIds(app);
            if isempty(selectedIds)
                return;
            end
            tf = any(strcmp(selectedIds, char(string(nodeId))));
        end

        function inputReport = currentResourceInputReport(app, node, spec)
            inputReport = struct();
            try
                report = app.LastValidationReport;
                if ~isstruct(report) || isempty(fieldnames(report))
                    return;
                end
                nodeId = char(string(getField(app, node, 'id', '')));
                nodeKey = matlab.lang.makeValidName(nodeId);
                if ~isfield(report, 'binding') || ~isfield(report.binding, 'nodes') || ~isfield(report.binding.nodes, nodeKey)
                    return;
                end
                br = report.binding.nodes.(nodeKey);
                if ~isfield(br, 'resources') || ~isfield(br.resources, 'inputs')
                    return;
                end
                inputs = br.resources.inputs;
                param = char(string(getField(app, spec, 'param', '')));
                for j = 1:numel(inputs)
                    if strcmp(char(string(getField(app, inputs(j), 'param', ''))), param)
                        inputReport = inputs(j);
                        return;
                    end
                end
            catch
                inputReport = struct();
            end
        end

        function label = resourceChoiceLabel(app, resource, spec)
            label = '';
            sourceNode = char(string(getField(app, resource, 'sourceNode', '')));
            sourceKind = lower(char(string(getField(app, resource, 'sourceKind', ''))));
            role = char(string(getField(app, resource, 'role', getField(app, spec, 'role', 'resource'))));
            type = char(string(getField(app, resource, 'type', getField(app, spec, 'type', 'resource'))));
            concrete = char(string(getField(app, resource, 'concreteName', '')));
            if ~isempty(sourceNode) && ~any(strcmp(sourceKind, {'context','ctx','runtime'}))
                if ~isempty(concrete)
                    label = ['<' role ' output from ' sourceNode ' / ' concrete '>'];
                else
                    label = ['<' role ' output from ' sourceNode '>'];
                end
            elseif ~isempty(concrete)
                label = concrete;
            else
                label = ['<' type '/' role '>'];
            end
        end

        function label = resourceSpecLabel(app, spec) %#ok<INUSD>
            type = char(string(getField(app, spec, 'type', 'resource')));
            role = char(string(getField(app, spec, 'role', '')));
            symbol = char(string(getField(app, spec, 'symbol', '')));
            if strcmpi(type, 'channel') && strcmpi(role, 'probability')
                label = 'channel/probability';
                return;
            elseif strcmpi(type, 'channel') && strcmpi(role, 'z_stack')
                label = 'channel/z-stack (DIC/BF)';
                return;
            elseif strcmpi(type, 'channel') && strcmpi(role, 'lineage_mother_mask')
                label = 'channel/lineage mother mask';
                return;
            elseif strcmpi(type, 'channel') && strcmpi(role, 'lineage_bud_mask')
                label = 'channel/lineage bud mask';
                return;
            elseif strcmpi(type, 'mask') && strcmpi(role, 'segmentation') && strcmpi(symbol, 'segmentation')
                label = 'mask/segmentation';
                return;
            end
            if isempty(role)
                label = type;
            else
                label = [type '/' role];
            end
        end

        function label = bindingResourceLabel(app, node, spec, param)
            label = resourceSpecLabel(app, spec);
            if strcmpi(getField(app,node,'pkg',''),'lstm_timeseries')&&~isempty(regexp(param,'^feature\d+$','once'))
                label=['Input ' regexp(param,'\d+$','match','once')];return;
            end
            if ~strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier')
                return;
            end
            pkgName = char(string(getField(app, node, 'pkg', '')));
            [executionSpec, hasExecutionSpec] = classifierExecutionSpec(app, pkgName);
            if ~hasExecutionSpec
                return;
            end
            [declaredLabel, found] = classifierSpecMetadataValue(app, ...
                executionSpec, 'labels', param);
            if found && ~isempty(strtrim(char(string(declaredLabel))))
                label = char(string(declaredLabel));
            end
        end

        function tooltip = bindingResourceTooltip(app, node, param, resourceLabel, isInput)
            if isInput
                tooltip = ['Input binding for ' resourceLabel '.'];
            else
                tooltip = ['Output binding for ' resourceLabel '.'];
            end
            if strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier')
                pkgName = char(string(getField(app, node, 'pkg', '')));
                [executionSpec, hasExecutionSpec] = classifierExecutionSpec(app, pkgName);
                if hasExecutionSpec
                    [declaredTip, found] = classifierSpecMetadataValue(app, ...
                        executionSpec, 'tips', param);
                    if found && ~isempty(strtrim(char(string(declaredTip))))
                        tooltip = [tooltip ' ' char(string(declaredTip))];
                    end
                end
            end
            if isInput
                tooltip = [tooltip ' Symbolic choices are resolved from upstream modules at run time.'];
            else
                tooltip = [tooltip ' This names the concrete resource written by the module.'];
            end
        end

        function tf = isSymbolicBindingLabel(app, value) %#ok<INUSD>
            value = strtrim(char(string(value)));
            tf = startsWith(value, '<') && endsWith(value, '>');
        end

        function value = symbolicBindingValueFromLabel(app, label)
            value = pipelineSymbolicBindingFromLabel(label);
        end

        function sourceNode = symbolicBindingSourceNode(app, value) %#ok<INUSD>
            sourceNode = '';
            value = strtrim(char(string(value)));
            if startsWith(value, '@resource:')
                parts = strsplit(value, ':');
                if numel(parts) >= 3
                    sourceNode = strtrim(parts{3});
                    sourceNode = dataSeriesNameFromVariableBindingForUi(app, sourceNode);
                end
                return;
            end
            if startsWith(value, '@')
                value = extractAfter(value, 1);
            end
            if contains(value, '.')
                parts = strsplit(value, '.');
                sourceNode = strtrim(parts{1});
                return;
            end
            tokens = regexp(value, 'output\s+from\s+([^/\s>]+)', 'tokens', 'once');
            if ~isempty(tokens)
                sourceNode = strtrim(tokens{1});
            end
        end

        function key = symbolicBindingResourceKey(app, value) %#ok<INUSD>
            key = '';
            value = strtrim(char(string(value)));
            if startsWith(value, '@resource:')
                parts = strsplit(value, ':');
                if numel(parts) >= 2
                    key = strtrim(parts{2});
                end
            end
            key = canonicalSymbolicResourceKey(app, key);
        end

        function key = canonicalSymbolicResourceKey(app, key) %#ok<INUSD>
            key = char(string(key));
            switch lower(strtrim(key))
                case {'lineage_cell','lineage_cell_mask','lineage_mask'}
                    key = 'lineage_mother';
                case {'lineage_conf','lineage_confidence'}
                    key = 'lineage_bud';
            end
        end

        function tf = isConfiguredBindingValue(app, value) %#ok<INUSD>
            tf = false;
            if isempty(value)
                return;
            end
            if iscell(value)
                flat = value(~cellfun(@isempty, value));
                if isempty(flat)
                    return;
                end
                % setparam often returns cell arrays as choice lists, with the
                % selected/default value duplicated at the end. Those are not
                % explicit user bindings and should not hide upstream symbols.
                if numel(flat) > 1
                    return;
                end
            end
            tf = true;
        end

        function tf = isSymbolicStoredBinding(app, value) %#ok<INUSD>
            tf = startsWith(strtrim(choiceScalarText(app, value)), '@');
        end

        function ctx = buildBindingValidationContext(app)
            ctx = struct('allowGUI', false);
            try
                ctx = buildRunContext(app);
            catch
            end
            ctx.allowGUI = false;
            ctx.interactive = false;
            ctx.dryRun = true;
            if ~isfield(ctx, 'roiList') || isempty(ctx.roiList)
                roiList = runtimeSelectedRois(app);
                if isempty(roiList)
                    ctx.roiList = 1;
                else
                    ctx.roiList = roiList;
                end
            end
            if ~isfield(ctx, 'channels') || isempty(ctx.channels)
                runtimeChannels = runtimeConcreteChannels(app);
                if isempty(runtimeChannels)
                    runtimeChannels = {'<runtime channel>'};
                end
                ctx.channels = runtimeChannels;
            end
            if ~isfield(ctx, 'roiChannels') || isempty(ctx.roiChannels)
                runtimeChannels = runtimeConcreteChannels(app);
                if ~isempty(runtimeChannels)
                    ctx.roiChannels = runtimeChannels;
                end
            end
            if ~isfield(ctx, 'masks') || isempty(ctx.masks)
                runtimeMasks = runtimeMaskChoices(app);
                if ~isempty(runtimeMasks)
                    ctx.masks = runtimeMasks;
                end
            end
            if ~isfield(ctx, 'dataSeriesNames') || isempty(ctx.dataSeriesNames)
                runtimeDataSeries = runtimeDataSeriesNames(app);
                if isempty(runtimeDataSeries)&&~isempty(app.BindingEditorClassifier)
                    inventory=numericTraceInventory(app);
                    runtimeDataSeries=unique({inventory.group},'stable');
                end
                if ~isempty(runtimeDataSeries)
                    ctx.dataSeriesNames = runtimeDataSeries;
                    ctx.dataSeries = runtimeDataSeries;
                end
            end
        end

        function txt = choiceScalarText(app, v) %#ok<INUSD>
            txt = '';
            if isempty(v)
                return;
            end
            if isMissingValue(app, v)
                return;
            end
            if iscell(v)
                flat = v(~cellfun(@isempty, v));
                flat = flat(~cellfun(@(x)isMissingValue(app, x), flat));
                if isempty(flat)
                    return;
                end
                txt = char(string(flat{end}));
            elseif ischar(v)
                txt = v;
            elseif isstring(v) || isnumeric(v) || islogical(v) || iscategorical(v)
                vals = string(v(:));
                try
                    vals = vals(~ismissing(vals));
                catch
                end
                if ~isempty(vals)
                    txt = char(vals(end));
                end
            else
                try
                    txt = char(string(v));
                catch
                    txt = '';
                end
            end
            txt = strtrim(txt);
        end

        function txt = bindingMultiValueToDisplay(app, v) %#ok<INUSD>
            txt = '';
            if isempty(v) || isMissingValue(app, v)
                return;
            end
            if iscell(v)
                flat = v(~cellfun(@isempty, v));
                flat = flat(~cellfun(@(x)isMissingValue(app, x), flat));
                if isempty(flat)
                    return;
                end
                txt = strjoin(cellstr(string(flat(:)')), ',');
            elseif isstring(v)
                txt = strjoin(cellstr(v(:)'), ',');
            elseif ischar(v)
                txt = v;
            elseif isnumeric(v) || islogical(v) || iscategorical(v)
                txt = strjoin(cellstr(string(v(:)')), ',');
            else
                try
                    txt = char(string(v));
                catch
                    txt = '';
                end
            end
            txt = strtrim(txt);
        end

        function choices = flattenChoiceList(app, value) %#ok<INUSD>
            choices = {};
            if isempty(value)
                return;
            end
            if iscell(value)
                for ii = 1:numel(value)
                    nested = flattenChoiceList(app, value{ii});
                    choices = [choices nested]; %#ok<AGROW>
                end
            elseif ischar(value)
                choices = {strtrim(value)};
            elseif isstring(value) || isnumeric(value) || islogical(value) || iscategorical(value)
                vals = cellstr(string(value(:)'));
                choices = cellfun(@(s)strtrim(char(string(s))), vals, 'UniformOutput', false);
            else
                try
                    choices = {strtrim(char(string(value)))};
                catch
                    choices = {};
                end
            end
            choices = unique(choices(~cellfun(@isempty, choices)), 'stable');
        end

        function grid = buildParamSection(app, parent, data, node, editable, scope)
            if nargin < 6 || isempty(scope)
                scope = 'static';
            end
            n = max(1, size(data, 1));
            grid = uigridlayout(parent, [n 2]);
            grid.RowHeight = repmat({28}, 1, n);
            grid.ColumnWidth = {210, '1x'};
            grid.Padding = [0 0 0 0];
            grid.RowSpacing = 6;
            grid.ColumnSpacing = 8;

            for i = 1:size(data, 1)
                key = char(string(data{i,1}));
                tooltip = paramTooltip(app, node, key, scope);
                label = uilabel(grid, 'Text', friendlyParamLabel(app, node, key));
                label.Layout.Row = i;
                label.Layout.Column = 1;
                if isempty(tooltip)
                    label.Tooltip = key;
                else
                    label.Tooltip = tooltip;
                end

                value = resolveDisplayedParamValue(app, node, key, data{i,2}, scope);
                ctrl = createParamControl(app, grid, node, key, value, editable, scope);
                ctrl.Layout.Row = i;
                ctrl.Layout.Column = 2;
                if ~isempty(tooltip)
                    try
                        ctrl.Tooltip = tooltip;
                    catch
                    end
                end
            end
        end

        function value = resolveDisplayedParamValue(app, node, key, defaultValue, scope)
            if nargin < 5 || isempty(scope)
                scope = 'static';
            end
            value = defaultValue;
            p = getField(app, node, 'params', struct());
            if strcmpi(scope, 'runtime')
                nodeId = char(string(getField(app, node, 'id', '')));
                rp = getRuntimeNodeParams(app, nodeId);
                if isstruct(rp) && isfield(rp, key)
                    value = rp.(key);
                elseif isstruct(p) && isfield(p, key) && ~isSymbolicStoredBinding(app, p.(key))
                    value = p.(key);
                end
            else
                if isstruct(p) && isfield(p, key)
                    value = p.(key);
                end
            end

            nodeType = char(string(getField(app, node, 'type', '')));
            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
            if strcmpi(scope, 'static') && strcmpi(nodeType, 'processor') && ...
                    strcmp(pkg, 'combinemultiplechannels')
                mode = combineMultipleChannelsModeForNode(app, node);
                if strcmpi(char(string(key)), 'requiredChannelCount')
                    if any(strcmp(mode, {'subtraction','division'}))
                        value = 2;
                    elseif isempty(value) || (ischar(value) && isempty(strtrim(value))) || (isstring(value) && strlength(strtrim(value)) == 0)
                        value = 0;
                    end
                end
            end
            if strcmpi(scope, 'static') && strcmpi(nodeType, 'classifier')
                switch lower(char(string(key)))
                    case 'outputmode'
                        if isempty(strtrim(char(string(value))))
                            value = 'lstm_only';
                        end
                    case 'executionenvironment'
                        if isempty(strtrim(char(string(value))))
                            value = 'module_default';
                        end
                    case 'outputtype'
                        if isempty(strtrim(char(string(value))))
                            value = 'segmentation';
                        end
                    case 'diameter'
                        if isempty(value) || (ischar(value) && isempty(strtrim(value))) || (isstring(value) && strlength(strtrim(value)) == 0)
                            value = NaN;
                        end
                    case 'min_size'
                        if isempty(value) || (ischar(value) && isempty(strtrim(value))) || (isstring(value) && strlength(strtrim(value)) == 0)
                            value = 10;
                        end
                    case 'flow_threshold'
                        if isempty(value) || (ischar(value) && isempty(strtrim(value))) || (isstring(value) && strlength(strtrim(value)) == 0)
                            value = 0.4;
                        end
                    case 'cell_prob_threshold'
                        if isempty(value) || (ischar(value) && isempty(strtrim(value))) || (isstring(value) && strlength(strtrim(value)) == 0)
                            value = 0;
                        end
                end
            end
            if strcmpi(scope, 'runtime') && strcmpi(nodeType, 'dataLoader') && strcmpi(char(string(key)), 'path')
                rawDataPath = effectiveRuntimeRawDataPath(app);
                if ~isempty(strtrim(rawDataPath))
                    value = rawDataPath;
                end
            end
        end

        function ctrl = createParamControl(app, parent, node, key, value, editable, scope)
            if nargin < 7 || isempty(scope)
                scope = 'static';
            end
            value = normalizeMissingParamValue(app, value);
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            keyLower = lower(char(string(key)));
            enableState = ternary(app, editable, 'on', 'off');

            if strcmp(nodeType, 'custom') && strcmp(keyLower, 'entrypoint')
                ctrl = uigridlayout(parent, [1 2]);
                ctrl.ColumnWidth = {'1x', 90};
                ctrl.RowHeight = {22};
                ctrl.Padding = [0 0 0 0];
                edit = uieditfield(ctrl, 'text', 'Value', char(string(value)), 'Enable', enableState);
                edit.ValueChangedFcn = @(src,~)paramControlChanged(app, node, key, src.Value, scope);
                btn = uibutton(ctrl, 'push', 'Text', 'Browse...', 'Enable', enableState);
                btn.ButtonPushedFcn = @(src,~)browseCustomFunction(app, node, ancestor(src, 'figure'));
                return;
            end
            if strcmp(nodeType, 'custom') && any(strcmp(keyLower, {'parametersjson','argumentsjson'}))
                ctrl = uieditfield(parent, 'text', 'Value', char(string(value)), 'Enable', enableState);
                ctrl.ValueChangedFcn = @(src,~)paramControlChanged(app, node, key, src.Value, scope);
                return;
            end

            if isBooleanParamKey(app, node, key) && isEmptyParamValue(app, value)
                defaults = defaultNodeParams(app, nodeType, getField(app, node, 'pkg', ''));
                if isstruct(defaults) && isfield(defaults, key)
                    value = normalizeMissingParamValue(app, defaults.(key));
                end
            end
            valueText = safeScalarText(app, value);
            if islogical(value) || any(strcmpi(valueText, {'true','false'})) || isBooleanParamKey(app, node, key)
                ctrl = uicheckbox(parent, 'Text', '');
                if islogical(value)
                    ctrl.Value = logical(value);
                elseif any(strcmpi(valueText, {'true','false'}))
                    ctrl.Value = strcmpi(valueText, 'true');
                else
                    ctrl.Value = false;
                end
                ctrl.Enable = enableState;
                ctrl.ValueChangedFcn = @(src,~)paramControlChanged(app, node, key, src.Value, scope);
                return;
            end

            choices = paramDropdownChoices(app, node, key);
            listChoices = valueListChoices(app, value);
            if isempty(choices) && ~isempty(listChoices)
                choices = listChoices;
            end
            if ~isempty(choices)
                ctrl = uidropdown(parent);
                ctrl.Items = choices;
                displayValue = choiceScalarText(app, value);
                if isempty(displayValue) || ~any(strcmp(choices, displayValue))
                    displayValue = choices{1};
                end
                ctrl.Value = displayValue;
                ctrl.Enable = enableState;
                ctrl.ValueChangedFcn = @(src,~)paramControlChanged(app, node, key, src.Value, scope);
                return;
            end

            if isnumeric(value) && isscalar(value)
                ctrl = uieditfield(parent, 'numeric');
                ctrl.Value = double(value);
                ctrlEnableState = enableState;
                if strcmpi(nodeType, 'processor') && strcmpi(char(string(getField(app, node, 'pkg', ''))), 'combinemultiplechannels') && ...
                        strcmp(keyLower, 'requiredchannelcount')
                    mode = combineMultipleChannelsModeForNode(app, node);
                    if any(strcmp(mode, {'subtraction','division'}))
                        ctrl.Value = 2;
                        ctrlEnableState = 'off';
                        ctrl.Tooltip = 'Arithmetic combination modes are fixed to exactly 2 channels.';
                    else
                        ctrl.Limits = [0 5];
                        try
                            ctrl.RoundFractionalValues = 'on';
                        catch
                        end
                        ctrl.Tooltip = 'Number of input channel bindings to expose. Use 0 for the legacy 5-slot mode.';
                    end
                end
                ctrl.Enable = ctrlEnableState;
                ctrl.ValueChangedFcn = @(src,~)paramControlChanged(app, node, key, src.Value, scope);
                if strcmpi(char(string(getField(app,node,'pkg',''))),'lstm_timeseries')&&strcmp(key,'featureCount')
                    ctrl.ValueChangedFcn=@(src,~)numericFeatureCountChanged(app,node,key,src,scope);
                end
                return;
            end

            if paramUsesFolderBrowser(app, node, key, value, scope)
                ctrl = uigridlayout(parent, [1 2]);
                ctrl.ColumnWidth = {'1x', 90};
                ctrl.RowHeight = {22};
                ctrl.Padding = [0 0 0 0];
                ctrl.ColumnSpacing = 6;

                edit = uieditfield(ctrl, 'text');
                edit.Layout.Row = 1;
                edit.Layout.Column = 1;
                edit.Value = paramValueToDisplay(app, node, key, value);
                edit.Enable = enableState;
                edit.ValueChangedFcn = @(src,~)paramControlChanged(app, node, key, src.Value, scope);

                btn = uibutton(ctrl, 'push', 'Text', 'Browse...');
                btn.Layout.Row = 1;
                btn.Layout.Column = 2;
                btn.Enable = enableState;
                btn.ButtonPushedFcn = @(~,~)browseParamFolder(app, node, key, edit, scope);
                return;
            end

            ctrl = uieditfield(parent, 'text');
            ctrl.Value = paramValueToDisplay(app, node, key, value);
            ctrl.Enable = enableState;
            ctrl.ValueChangedFcn = @(src,~)paramControlChanged(app, node, key, src.Value, scope);
        end

        function browseCustomFunction(app, node, dialog)
            [file, folder] = uigetfile('*.m', 'Select a MATLAB function');
            if isequal(file, 0), return; end
            try
                patch = customModule.referenceForFile(fullfile(folder, file), app.CurrentPipelinePath);
                idx = find(strcmp({app.Data.nodes.id}, char(string(node.id))), 1);
                if isempty(idx), return; end
                app.Data.nodes(idx).params.entryPoint = patch.entryPoint;
                app.Data.nodes(idx).params.codeFolder = patch.codeFolder;
                markPipelineDirty(app, true);
                refreshAfterModelChange(app);
                if ~isequal(dialog, app.UIFigure) && isvalid(dialog) && isstruct(dialog.UserData)
                    populateStaticParametersDialogBody(app, dialog.UserData.bodyPanel, char(string(node.id)));
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Custom function', 'Icon', 'error');
            end
        end

        function tf = isEmptyParamValue(app, value)
            value = normalizeMissingParamValue(app, value);
            if isempty(value)
                tf = true;
            elseif ischar(value) || (isstring(value) && isscalar(value))
                tf = isempty(strtrim(char(string(value))));
            else
                tf = false;
            end
        end

        function tf = paramUsesFolderBrowser(app, node, key, value, scope) %#ok<INUSD>
            if ~strcmpi(char(string(scope)), 'static')
                tf = false;
                return;
            end
            try
                contract = pipelineNodeContract(node);
            catch
                contract = struct();
            end
            if isstruct(contract) && isfield(contract, 'parameters') && isstruct(contract.parameters) ...
                    && isfield(contract.parameters, 'paths') && ~isempty(contract.parameters.paths)
                pathKeys = cellstr(string(contract.parameters.paths(:)));
                if any(strcmp(pathKeys, char(string(key))))
                    tf = ~isnumeric(value);
                    return;
                end
            end
            keyLower = lower(char(string(key)));
            tf = any(strcmp(keyLower, {'outputdir','outputfolder','outputpath','folder','directory'})) || ...
                endsWith(keyLower, 'dir') || endsWith(keyLower, 'folder');
            if tf && isnumeric(value)
                tf = false;
            end
        end

        function tf = isBooleanParamKey(app, node, key)
            tf = false;
            keyText = char(string(key));
            try
                choices = paramDropdownChoices(app, node, keyText);
                if isBooleanChoiceList(app, choices)
                    tf = true;
                    return;
                end
            catch
            end

            keyLower = lower(keyText);
            tf = startsWith(keyLower, {'infer','enable','disable','use','show','activate','overwrite','write','correct','crop','force','resume'}) || ...
                startsWith(keyLower, 'is') || startsWith(keyLower, 'has') || ...
                endsWith(keyLower, {'enabled','active','checkbox'});
        end

        function tf = isBooleanChoiceList(app, choices) %#ok<INUSD>
            tf = false;
            if isempty(choices)
                return;
            end
            try
                flat = string([choices{:}]);
            catch
                try
                    flat = string(choices(:)');
                catch
                    return;
                end
            end
            flat = lower(strtrim(flat(~ismissing(flat))));
            tf = numel(flat) == 2 && all(ismember(flat, ["true","false"]));
        end

        function browseParamFolder(app, node, key, editField, scope)
            startDir = '';
            try
                startDir = char(string(editField.Value));
            catch
                startDir = '';
            end
            if isempty(startDir) || exist(startDir, 'dir') ~= 7
                startDir = currentProjectFolder(app);
            end
            if isempty(startDir) || exist(startDir, 'dir') ~= 7
                startDir = pwd;
            end
            pth = uigetdir(startDir, ['Select folder for ' char(string(key))]);
            if isequal(pth, 0)
                return;
            end
            try
                editField.Value = pth;
            catch
            end
            paramControlChanged(app, node, key, pth, scope);
        end

        function folder = currentProjectFolder(app)
            folder = '';
            try
                if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                    [pth, name] = app.CurrentProject.getPath;
                    candidate = fullfile(pth, name);
                    if exist(candidate, 'dir') == 7
                        folder = candidate;
                    elseif exist(pth, 'dir') == 7
                        folder = pth;
                    end
                end
            catch
                folder = '';
            end
        end

        function choices = paramDropdownChoices(app, node, key) %#ok<INUSD>
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            keyLower = lower(char(string(key)));
            choices = {};
            switch nodeType
                case 'custom'
                    if strcmp(keyLower, 'callmode'), choices = {'context','arguments'}; end
                case 'classifier'
                    pkgName = char(string(getField(app, node, 'pkg', '')));
                    [spec, hasExecutionSpec] = classifierExecutionSpec(app, pkgName);
                    if hasExecutionSpec
                        [declaredChoices, found] = classifierSpecMetadataValue(app, spec, 'choices', key);
                        if found
                            choices = flattenChoiceList(app, declaredChoices);
                            if ~isempty(choices)
                                return;
                            end
                        end
                    end
                    switch keyLower
                        case 'outputmode'
                            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
                            if strcmp(pkg, 'cnn_lstm')
                                spec = cnnLstmExecutionSpec(app);
                                if isfield(spec, 'choices') && isfield(spec.choices, 'outputMode')
                                    choices = spec.choices.outputMode;
                                else
                                    choices = {'lstm_only','cnn_only','both'};
                                end
                            else
                                choices = {'lstm_only','cnn_only','both'};
                            end
                        case 'outputtype'
                            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
                            if strcmp(pkg, 'cellposesam')
                                spec = cellposeExecutionSpec(app);
                                if isfield(spec, 'choices') && isfield(spec.choices, 'outputType')
                                    choices = spec.choices.outputType;
                                else
                                    choices = {'segmentation','probability','both'};
                                end
                            elseif strcmp(pkg, 'deeplab_pixel_classification')
                                spec = deeplabPixelExecutionSpec(app);
                                if isfield(spec, 'choices') && isfield(spec.choices, 'outputType')
                                    choices = spec.choices.outputType;
                                else
                                    choices = {'segmentation','probability','both'};
                                end
                            else
                                choices = {'segmentation','probability','both'};
                            end
                        case 'executionenvironment'
                            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
                            if strcmp(pkg, 'cnn_lstm')
                                spec = cnnLstmExecutionSpec(app);
                                if isfield(spec, 'choices') && isfield(spec.choices, 'executionEnvironment')
                                    choices = spec.choices.executionEnvironment;
                                else
                                    choices = {'module_default','cpu','gpu'};
                                end
                            elseif strcmp(pkg, 'deeplab_pixel_classification')
                                spec = deeplabPixelExecutionSpec(app);
                                if isfield(spec, 'choices') && isfield(spec.choices, 'executionEnvironment')
                                    choices = spec.choices.executionEnvironment;
                                else
                                    choices = {'module_default','cpu','gpu'};
                                end
                            end
                        case 'resolution'
                            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
                            if strcmp(pkg, 'sam31')
                                spec = sam31ExecutionSpec(app);
                                if isfield(spec, 'choices') && isfield(spec.choices, 'resolution')
                                    choices = spec.choices.resolution;
                                else
                                    choices = {'280','1008'};
                                end
                            end
                        case 'sam31runner'
                            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
                            if strcmp(pkg, 'sam31')
                                spec = sam31ExecutionSpec(app);
                                if isfield(spec, 'choices') && isfield(spec.choices, 'sam31Runner')
                                    choices = spec.choices.sam31Runner;
                                else
                                    choices = {'session','external'};
                                end
                            end
                        case {'pretrainedmodel','trackingmode','device','normalizeimages'}
                            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
                            if strcmp(pkg, 'trackastra')
                                spec = trackastraExecutionSpec(app);
                                keyName = key;
                                if isfield(spec, 'choices') && isfield(spec.choices, keyName)
                                    choices = spec.choices.(keyName);
                                end
                            end
                    end
                case 'roiextract'
                    switch keyLower
                        case 'executionmode'
                            choices = {'parallel','sequential'};
                        case 'driftmethod'
                            choices = {'robust','subpixel','circshift','register'};
                        case 'driftrefmode'
                            choices = {'fixed','previous'};
                        case 'driftchannel'
                            choices = runtimeChannelChoices(app, true);
                    end
                case 'processor'
                    pkg = lower(char(string(getField(app, node, 'pkg', ''))));
                    if strcmp(pkg, 'combinemultiplechannels') && strcmp(keyLower, 'mode')
                        choices = {'additive','subtraction','division'};
                    elseif strcmp(pkg, 'computemetrics') && strcmp(keyLower, 'backgroundmethod')
                        choices = {'mean','median','percentile'};
                    elseif strcmp(pkg, 'computemetrics') && ~isempty(regexp(keyLower, '^mask\d+_backgroundlabel$', 'once'))
                        choices = {'auto','0','1'};
                    elseif strcmp(pkg, 'computerls') && strcmp(keyLower, 'statedecoder')
                        choices = {'off','viterbi','median'};
                    elseif strcmp(pkg, 'singlecelloscillations')
                        switch keyLower
                            case 'baselinemethod'
                                choices = {'moving_mean','moving_median','none'};
                            case 'baselineendpoints'
                                choices = {'discard','shrink','fill'};
                            case 'fluorescencevariable'
                                p = getField(app, node, 'params', struct());
                                selector = '';
                                if isstruct(p) && isfield(p, 'fluorescence_data') && ~isempty(p.fluorescence_data)
                                    selector = choiceScalarText(app, p.fluorescence_data);
                                end
                                vars = runtimeDataSeriesVariableNames(app, selector);
                                if ~isempty(vars)
                                    choices = [{'auto'} vars];
                                end
                        end
                    end
            end
        end

        function choices = valueListChoices(app, value) %#ok<INUSD>
            choices = {};
            if ~iscell(value) || numel(value) < 2
                return;
            end
            try
                choices = flattenChoiceList(app, value);
            catch
                choices = {};
            end
        end

        function choices = runtimeChannelChoices(app, includeEmpty)
            if nargin < 2
                includeEmpty = false;
            end
            choices = {};
            if includeEmpty
                choices = {'auto'};
            end
            if isfield(app.RuntimeParseInfo, 'channels') && ~isempty(app.RuntimeParseInfo.channels)
                parsed = cellstr(string(app.RuntimeParseInfo.channels(:)'));
                choices = [choices parsed]; %#ok<AGROW>
            end
            choices = unique(choices(~cellfun(@isempty, choices)), 'stable');
        end

        function numericFeatureCountChanged(app,node,key,src,scope)
            dialog=ancestor(src,'figure');
            paramControlChanged(app,node,key,src.Value,scope);
            if ~isequal(dialog,app.UIFigure)&&isvalid(dialog)&&isstruct(dialog.UserData)&&isfield(dialog.UserData,'bodyPanel')
                nodeId=char(string(node.id));
                populateStaticParametersDialogBody(app,dialog.UserData.bodyPanel,nodeId);
                idx=find(strcmp({app.Data.nodes.id},nodeId),1);
                if ~isempty(idx),resizeStaticParametersDialog(app,dialog,app.Data.nodes(idx));end
            end
        end

        function paramControlChanged(app, node, key, value, scope)
            if nargin < 6 || isempty(scope)
                scope = 'static';
            end
            nodeId = char(string(getField(app, node, 'id', '')));
            idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
            if isempty(idx)
                return;
            end
            focus = captureTabFocus(app);
            d = openRuntimeProgress(app, 'Pipeline parameters', 'Applying parameter change...');
            drawnow limitrate nocallbacks;
            cleanupObj = onCleanup(@()finishStaticParameterRefresh(app, d, [], focus)); %#ok<NASGU>
            if strcmpi(char(string(key)), 'driftChannel') && strcmpi(char(string(value)), 'auto')
                value = [];
            end
            nodeType = char(string(getField(app, app.Data.nodes(idx), 'type', '')));
            pkg = lower(char(string(getField(app, app.Data.nodes(idx), 'pkg', ''))));
            keyText = char(string(key));
            if strcmpi(scope, 'static') && strcmpi(nodeType, 'classifier') && ...
                    strcmp(pkg, 'cellposesam') && strcmpi(keyText, 'outputType')
                value = normalizeCellposeOutputTypeForPipeline(app, value);
            elseif strcmpi(scope, 'static') && strcmpi(nodeType, 'classifier') && ...
                    strcmp(pkg, 'deeplab_pixel_classification') && strcmpi(keyText, 'outputType')
                value = normalizeDeeplabPixelOutputTypeForPipeline(app, value);
            elseif strcmpi(scope, 'static') && strcmpi(nodeType, 'classifier') && ...
                    strcmp(pkg, 'cnn_lstm') && strcmpi(keyText, 'outputMode')
                value = normalizeCnnLstmOutputModeForPipeline(app, value);
            elseif strcmpi(scope, 'static') && strcmpi(nodeType, 'classifier') && ...
                    strcmp(pkg, 'sam31') && strcmpi(keyText, 'sam31Runner')
                value = normalizeSam31RunnerForPipeline(app, value);
            elseif strcmpi(scope, 'static') && strcmpi(nodeType, 'classifier') && ...
                    any(strcmp(pkg, {'cnn_lstm','deeplab_pixel_classification'})) && strcmpi(keyText, 'executionEnvironment')
                value = normalizeExecutionEnvironmentForPipeline(app, value);
            end
            if strcmpi(scope, 'static') && strcmpi(nodeType, 'processor') && ...
                    strcmp(pkg, 'combinemultiplechannels') && strcmpi(keyText, 'requiredChannelCount')
                value = normalizeCombineChannelCount(app, value);
            elseif strcmpi(scope, 'static') && strcmpi(nodeType, 'processor') && ...
                    strcmp(pkg, 'combinemultiplechannels') && strcmpi(keyText, 'mode')
                value = normalizeCombineMultipleChannelsMode(app, value);
            elseif strcmpi(scope, 'static') && strcmpi(nodeType, 'processor') && ...
                    strcmp(pkg, 'computemetrics') && any(strcmpi(keyText, {'maskChannelCount','scoreChannelCount'}))
                if strcmpi(keyText, 'maskChannelCount')
                    value = normalizeCountValue(app, value, 1, 8);
                else
                    value = normalizeCountValue(app, value, 0, 12);
                end
            end

            if strcmpi(scope,'static')&&strcmp(pkg,'lstm_timeseries')&&strcmp(keyText,'featureCount')
                value=normalizeCountValue(app,value,1,128);
            end
            if strcmpi(scope, 'runtime')
                runtimeParams = getRuntimeNodeParams(app, nodeId);
                runtimeParams.(keyText) = value;
                setRuntimeNodeParams(app, nodeId, runtimeParams);
                if strcmpi(nodeType, 'dataLoader') && strcmpi(keyText, 'path')
                    setRuntimeValue(app, 'rawDataPath', value);
                end
            else
                if ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                    app.Data.nodes(idx).params = struct();
                end
                app.Data.nodes(idx).params.(keyText) = value;
                if strcmp(pkg,'lstm_timeseries')&&strcmp(keyText,'featureCount')
                    cfg=lstm_timeseries.parameters(struct('trainingParam',app.Data.nodes(idx).params));
                    for i=1:cfg.featureCount
                        for field={sprintf('feature%d',i),sprintf('featureColumn%d',i)}
                            if ~isfield(app.Data.nodes(idx).params,field{1}),app.Data.nodes(idx).params.(field{1})=cfg.(field{1});end
                        end
                    end
                end
                if strcmpi(nodeType, 'processor') && strcmp(pkg, 'combinemultiplechannels') && strcmpi(keyText, 'mode')
                    if any(strcmp(char(string(value)), {'subtraction','division'}))
                        app.Data.nodes(idx).params.requiredChannelCount = 2;
                    end
                end
                markPipelineDirty(app, true);
            end
            needsBindingRefresh = ~strcmpi(scope, 'runtime') && ...
                (any(strcmpi(keyText, {'outputMode','outputType'})) || ...
                staticParamAffectsBindings(app, app.Data.nodes(idx), keyText));
            if needsBindingRefresh
                if strcmpi(nodeType, 'custom') && any(strcmpi(keyText, {'inputPorts','outputPorts'}))
                    rebuildEdgesFromLayout(app);
                end
                updateRuntimeProgress(app, d, 'Rebuilding changed module tab...');
                rebuildModuleTabForNode(app, idx);
            end
            updateRuntimeProgress(app, d, 'Checking pipeline bindings...');
            refreshValidationReport(app, needsBindingRefresh);
        end

        function value = normalizeCombineChannelCount(app, value) %#ok<INUSD>
            value = normalizeCountValue(app, value, 0, 5);
        end

        function value = normalizeCombineMultipleChannelsMode(app, value) %#ok<INUSD>
            try
                value = lower(strtrim(char(string(value))));
            catch
                value = 'additive';
            end
            switch value
                case {'add','sum','rgb'}
                    value = 'additive';
                case {'subtract','difference'}
                    value = 'subtraction';
                case {'divide','ratio','quotient'}
                    value = 'division';
                case {'additive','subtraction','division'}
                    % keep
                otherwise
                    value = 'additive';
            end
        end

        function value = normalizeCountValue(app, value, minValue, maxValue) %#ok<INUSD>
            try
                value = double(value);
            catch
                value = 0;
            end
            if isempty(value) || ~isscalar(value) || ~isfinite(value)
                value = 0;
            end
            value = min(maxValue, max(minValue, round(value)));
        end

        function tf = staticParamAffectsBindings(app, node, key)
            nodeType = char(string(getField(app, node, 'type', '')));
            pkg = char(string(getField(app, node, 'pkg', '')));
            keyText = char(string(key));
            tf = (strcmpi(nodeType, 'processor') && ...
                strcmpi(pkg, 'combinemultiplechannels') && ...
                any(strcmpi(keyText, {'requiredChannelCount','mode'}))) || ...
                (strcmpi(nodeType, 'processor') && ...
                strcmpi(pkg, 'computemetrics') && ...
                any(strcmpi(keyText, {'maskChannelCount','scoreChannelCount'}))) || ...
                (strcmpi(nodeType, 'processor') && ...
                strcmpi(pkg, 'computeRLS') && ...
                strcmpi(keyText, 'AverageFluoByDivision')) || ...
                (strcmpi(nodeType, 'classifier') && ...
                strcmpi(pkg, 'cellposesam') && ...
                strcmpi(keyText, 'outputType')) || ...
                (strcmpi(nodeType, 'classifier') && ...
                strcmpi(pkg, 'cellLatentModel') && ...
                any(strcmpi(keyText, {'backend','temporalVariant'}))) || ...
                (strcmpi(nodeType, 'classifier') && ...
                strcmpi(pkg, 'deeplab_pixel_classification') && ...
                strcmpi(keyText, 'outputType')) || ...
                (strcmpi(nodeType,'classifier')&&strcmpi(pkg,'lstm_timeseries')&&strcmpi(keyText,'featureCount')) || ...
                (strcmpi(nodeType, 'custom') && ...
                any(strcmpi(keyText, {'inputPorts','outputPorts','argumentsJson','callMode','parametersJson'})));
        end

        function data = paramsToTableData(app, node, scope)
            keys = moduleParamKeys(app, node, scope);
            keys = unique(keys(~cellfun(@isempty, keys)), 'stable');
            keys = filterParamsByAdvancedMode(app, node, keys);
            p = getField(app, node, 'params', struct());
            defaults = defaultNodeParams(app, getField(app, node, 'type', ''), getField(app, node, 'pkg', ''));
            data = cell(numel(keys), 2);
            for i = 1:numel(keys)
                data{i,1} = keys{i};
                if isstruct(p) && isfield(p, keys{i})
                    data{i,2} = paramValueToDisplay(app, node, keys{i}, p.(keys{i}));
                elseif isstruct(defaults) && isfield(defaults, keys{i})
                    data{i,2} = paramValueToDisplay(app, node, keys{i}, defaults.(keys{i}));
                else
                    data{i,2} = '';
                end
            end
        end

        function vals = getParamList(app, params, fieldName) %#ok<INUSD>
            vals = {};
            if isstruct(params) && isfield(params, fieldName) && ~isempty(params.(fieldName))
                vals = cellstr(string(params.(fieldName)(:)))';
            end
        end

        function keys = moduleParamKeys(app, node, scope)
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            pkgName = char(string(getField(app, node, 'pkg', '')));
            pkg = lower(pkgName);
            isStatic = strcmpi(scope, 'static');

            if isStatic
                switch nodeType
                    case 'dataloader'
                        keys = {};
                    case 'roigrid'
                        keys = {'gridCount'};
                    case {'roipattern','roiidentify'}
                        keys = {'threshold'};
                    case 'roimanual'
                        keys = {};
                    case 'roiextract'
                        keys = {'executionMode','correctDrift','driftChannel','driftMethod','driftRefMode','driftSubpixel','driftMaxShift','scale','cropDrift','forceChannelNames'};
                    case 'processor'
                        keys = processorStaticKeys(app, pkg, node);
                    case 'classifier'
                        app.ensureCustomPackagePathForNode(node);
                        if strcmpi(pkgName,'lstm_timeseries')
                            spec=lstm_timeseries.executionSpec(struct('trainingParam',getField(app,node,'params',struct())));
                            keys=spec.staticKeys;
                        else
                            keys = classifierStaticKeys(app, pkgName, node);
                        end
                    otherwise
                        keys = contractParamKeys(app, node, scope);
                end
            else
                switch nodeType
                    case 'dataloader'
                        keys = {};
                    case {'roipattern','roiidentify'}
                        keys = {};
                    case 'roimanual'
                        keys = {};
                    case 'roigrid'
                        keys = {};
                    case 'roitracked'
                        keys = {};
                    case 'roiextract'
                        keys = {};
                    case 'processor'
                        keys = processorRuntimeKeys(app, pkg);
                    case 'classifier'
                        keys = {};
                    otherwise
                        keys = contractParamKeys(app, node, scope);
                end
            end
            keys = removeGlobalRuntimeKeys(app, keys);
        end

        function keys = contractParamKeys(app, node, scope)
            try
                contract = pipelineNodeContract(node);
            catch
                contract = struct();
            end
            keys = {};
            if isstruct(contract) && isfield(contract, 'parameters') && isstruct(contract.parameters)
                params = contract.parameters;
                switch lower(scope)
                    case 'static'
                        keys = [cellstr(getParamList(app, params, 'static')), ...
                            cellstr(getParamList(app, params, 'fixed')), ...
                            cellstr(getParamList(app, params, 'design')), ...
                            cellstr(getParamList(app, params, 'template'))];
                    otherwise
                        keys = [cellstr(getParamList(app, params, 'run')), ...
                            cellstr(getParamList(app, params, 'data'))];
                end
            end
        end

        function keys = processorStaticKeys(app, pkg, node)
            if nargin < 3
                node = struct();
            end
            app.ensureCustomPackagePathForNode(node);
            switch pkg
                case 'combinemultiplechannels'
                    keys = combineMultipleChannelsStaticKeysForNode(app, node);
                case 'computemetrics'
                    keys = computeMetricsStaticKeysForNode(app, node);
                case 'computerls'
                    keys = moduleSetparamKeys(app, pkg);
                    keys = removeBindingSelectorKeys(app, keys, node);
                    keys = setdiff(keys, {'metrics_data','outputName','pkg','paramTooltip','tip'}, 'stable');
                case 'computelineage'
                    keys = moduleSetparamKeys(app, pkg);
                    keys = removeBindingSelectorKeys(app, keys, node);
                    keys = setdiff(keys, {'outputName','pkg','tip'}, 'stable');
                case 'computemaxprojection'
                    keys = {'method','zstacks'};
                case 'basicobjecttracking'
                    keys = {'inputMode','coefDist','coefSize','coefIoU','maxRelativeDistance','debug'};
                case 'trackmotherlineageviterbi'
                    keys = {'mode','debug', ...
                        'wM_center','wM_area','wM_bottom','wB_dist','wB_small', ...
                        'lambdaM_jump','lambdaM_area','lambdaM_appear','lambdaM_disapp', ...
                        'lambdaB_jump','lambdaB_area','lambdaB_appear','lambdaB_disapp', ...
                        'tempConf','bottomSign','ratioMin','bonusSwitch'};
                otherwise
                    keys = contractParamKeys(app, node, 'static');
                    if isempty(keys)
                        keys = moduleSetparamKeys(app, pkg);
                    end
                    keys = removeBindingSelectorKeys(app, keys, node);
                    keys = removeResourceOutputNameKeys(app, keys, node);
                    keys = setdiff(keys, {'outputName','outputChannelName','existingPolicy','pkg','paramTooltip','tip', ...
                        'moduleVar','modulePath','moduleId','description','category','customPackageRoot', ...
                        'customPackageDir','customPackageLoadedAt'}, 'stable');
            end
        end

        function keys = removeBindingSelectorKeys(app, keys, node) %#ok<INUSD>
            try
                contract = pipelineNodeContract(node);
            catch
                contract = struct();
            end
            selectorKeys = {};
            try
                if isstruct(contract) && isfield(contract, 'binding') && isstruct(contract.binding) ...
                        && isfield(contract.binding, 'selectorKeys') && ~isempty(contract.binding.selectorKeys)
                    selectorKeys = cellstr(string(contract.binding.selectorKeys(:)));
                end
            catch
                selectorKeys = {};
            end
            if ~isempty(selectorKeys)
                keys = setdiff(keys, selectorKeys, 'stable');
            end
        end

        function keys = removeResourceOutputNameKeys(app, keys, node) %#ok<INUSD>
            try
                contract = pipelineNodeContract(node);
            catch
                contract = struct();
            end
            outputKeys = {};
            try
                resources = getField(app, contract, 'resources', struct());
                outputs = getField(app, resources, 'out', struct([]));
                for i = 1:numel(outputs)
                    nameParam = char(string(getField(app, outputs(i), 'nameParam', '')));
                    param = char(string(getField(app, outputs(i), 'param', '')));
                    if ~isempty(nameParam)
                        outputKeys{end+1} = nameParam; %#ok<AGROW>
                    elseif ~isempty(param)
                        outputKeys{end+1} = param; %#ok<AGROW>
                    end
                end
            catch
                outputKeys = {};
            end
            if ~isempty(outputKeys)
                keys = setdiff(keys, unique(outputKeys, 'stable'), 'stable');
            end
        end

        function n = combineMultipleChannelsSlotCountForNode(app, node) %#ok<INUSD>
            maxSlots = 5;
            mode = combineMultipleChannelsModeForNode(app, node);
            if any(strcmp(mode, {'subtraction','division'}))
                n = 2;
                return;
            end
            n = maxSlots;
            p = getField(app, node, 'params', struct());
            if ~isstruct(p) || ~isfield(p, 'requiredChannelCount') || isempty(p.requiredChannelCount)
                return;
            end
            try
                requested = double(p.requiredChannelCount);
            catch
                requested = 0;
            end
            if ~isscalar(requested) || ~isfinite(requested) || requested <= 0
                return;
            end
            n = min(maxSlots, max(1, round(requested)));
        end

        function mode = combineMultipleChannelsModeForNode(app, node) %#ok<INUSD>
            mode = 'additive';
            p = getField(app, node, 'params', struct());
            if isstruct(p) && isfield(p, 'mode') && ~isempty(p.mode)
                try
                    mode = lower(strtrim(char(string(p.mode))));
                catch
                    mode = 'additive';
                end
            end
            switch mode
                case {'add','sum','rgb'}
                    mode = 'additive';
                case {'subtract','difference'}
                    mode = 'subtraction';
                case {'divide','ratio','quotient'}
                    mode = 'division';
                case {'additive','subtraction','division'}
                    % keep
                otherwise
                    mode = 'additive';
            end
        end

        function keys = combineMultipleChannelsStaticKeysForNode(app, node)
            slotCount = combineMultipleChannelsSlotCountForNode(app, node);
            mode = combineMultipleChannelsModeForNode(app, node);
            keys = {'mode', 'requiredChannelCount'};
            switch mode
                case 'additive'
                    rgbKeys = arrayfun(@(i)sprintf('RGB_Channel%d', i), 1:slotCount, 'UniformOutput', false);
                    keys = [keys, rgbKeys];
                case 'division'
                    offsetKeys = arrayfun(@(i)sprintf('Offset_Channel%d', i), 1:min(2, slotCount), 'UniformOutput', false);
                    keys = [keys, offsetKeys];
            end
            keys = [keys, {'debug'}];
        end

        function keys = computeMetricsStaticKeysForNode(app, node)
            maskCount = computeMetricsMaskSlotCountForNode(app, node);
            keys = {'maskChannelCount','scoreChannelCount'};
            for i = 1:maskCount
                keys = [keys, { ...
                    sprintf('mask%d_label', i), ...
                    sprintf('mask%d_stat', i), ...
                    sprintf('mask%d_backgroundLabel', i), ...
                    sprintf('mask%d_scoreLabel', i)}]; %#ok<AGROW>
            end
            keys = [keys, {'BrightestPixels','backgroundMethod','backgroundPercentile','backgroundDilatePx','computeMaskCombinations'}];
        end

        function n = computeMetricsMaskSlotCountForNode(app, node)
            n = computeMetricsCountForNode(app, node, {'maskChannelCount','maskCount'}, 2, 1, 8);
        end

        function n = computeMetricsScoreSlotCountForNode(app, node)
            n = computeMetricsCountForNode(app, node, {'scoreChannelCount','channelCount'}, 4, 0, 12);
        end

        function n = computeMetricsCountForNode(app, node, names, defaultValue, minValue, maxValue) %#ok<INUSD>
            n = defaultValue;
            p = getField(app, node, 'params', struct());
            if isstruct(p)
                for i = 1:numel(names)
                    key = char(string(names{i}));
                    if isfield(p, key) && ~isempty(p.(key))
                        requested = numericScalarParamValueForUi(app, p.(key));
                        if isscalar(requested) && isfinite(requested)
                            n = requested;
                            break;
                        end
                    end
                end
            end
            n = min(maxValue, max(minValue, round(n)));
        end

        function value = numericScalarParamValueForUi(app, raw) %#ok<INUSD>
            value = NaN;
            if isnumeric(raw) || islogical(raw)
                candidate = double(raw);
            elseif ischar(raw) || (isstring(raw) && isscalar(raw))
                candidate = str2double(strtrim(char(string(raw))));
            elseif iscell(raw) && numel(raw) == 1
                candidate = numericScalarParamValueForUi(app, raw{1});
            else
                candidate = NaN;
            end
            if isscalar(candidate) && isfinite(candidate)
                value = candidate;
            end
        end

        function keys = classifierStaticKeys(app, pkg, node)
            [spec, hasExecutionSpec] = classifierExecutionSpec(app, pkg);
            if hasExecutionSpec
                keys = unique([spec.staticKeys classifierPipelineArtifactKeys(app, node)], 'stable');
                return;
            end
            switch lower(char(string(pkg)))
                case 'cnn_lstm'
                    spec = cnnLstmExecutionSpec(app);
                    keys = spec.staticKeys;
                case 'cellposesam'
                    spec = cellposeExecutionSpec(app);
                    keys = spec.staticKeys;
                case 'sam31'
                    spec = sam31ExecutionSpec(app);
                    keys = spec.staticKeys;
                case 'trackastra'
                    spec = trackastraExecutionSpec(app);
                    keys = spec.staticKeys;
                case 'deeplab_pixel_classification'
                    spec = deeplabPixelExecutionSpec(app);
                    keys = spec.staticKeys;
                otherwise
                    keys = {};
            end
        end

        function keys = classifierPipelineArtifactKeys(app, node)
            % Only a module's explicit contract may grant pipeline ownership
            % of model/environment paths. Runtime loading and path ownership
            % are independent; private classifier settings stay private.
            keys = {};
            policy = pipelineClassifierReferencePolicy(node);
            if ~policy.specAvailable || ~policy.semanticContractDeclared
                return;
            end
            try
                if startsWith(policy.semanticContractSource, 'executionSpec.')
                    field = extractAfter(policy.semanticContractSource, 'executionSpec.');
                    declaration = policy.spec.(char(field));
                else
                    manifest = jsondecode(fileread(policy.semanticContractSource));
                    declaration = feval(manifest.entrypoints.contract, getField(app, node, 'params', struct()));
                end
                parameters = getField(app, declaration, 'parameters', struct());
                paths = normalizeClassifierSpecKeys(app, getField(app, parameters, 'paths', {}));
                artifacts = normalizeClassifierSpecKeys(app, getField(app, policy.spec, 'artifactKeys', {}));
                environment = normalizeClassifierSpecKeys(app, getField(app, policy.spec, 'environmentKeys', {}));
                candidates = [artifacts environment];
                keys = intersect(candidates, paths, 'stable');
            catch
                % Failed metadata cannot grant ownership to hidden paths.
            end
        end

        function keys = moduleSetparamKeys(app, pkg)
            keys = {};
            if isempty(pkg)
                return;
            end
            pkg = canonicalProcessorPackageName(app, pkg);
            try
                p = feval([char(string(pkg)) '.setparam'], moduleSetparamPreviewContext(app, pkg));
                if isstruct(p)
                    keys = fieldnames(p)';
                end
            catch
                keys = {};
            end
        end

        function ctx = moduleSetparamPreviewContext(app, pkg)
            % Avoid legacy setparam fallbacks that scan the MATLAB workspace
            % and load ROI dataseries while pipeline2 is only discovering UI keys.
            ctx = struct();
            ctx.useProvidedChannels = true;
            ctx.channels = {'N/A'};
            ctx.roiChannels = {'N/A'};
            ctx.masks = {'N/A'};
            ctx.dataSeries = {''};
            ctx.dataSeriesNames = {''};
            ctx.classification_data = {''};
            ctx.classificationData = {''};
            projectFolder = currentProjectFolder(app);
            if ~isempty(projectFolder)
                ctx.projectPath = projectFolder;
                ctx.run = struct('projectPath', projectFolder);
                ctx.io = struct('projectPath', projectFolder);
                ctx.targetRef = struct('projectPath', projectFolder);
            end
            try
                channels = runtimeConcreteChannels(app);
                if ~isempty(channels)
                    ctx.channels = channels;
                    ctx.roiChannels = channels;
                end
            catch
            end
            try
                ds = runtimeCachedDataSeriesNames(app);
                if ~isempty(ds)
                    ctx.dataSeries = ds;
                    ctx.dataSeriesNames = ds;
                    ctx.classification_data = ds;
                    ctx.classificationData = ds;
                end
            catch
            end
            switch lower(char(string(pkg)))
                case {'computerls','computelineage'}
                    if isempty(ctx.classification_data)
                        ctx.classification_data = {''};
                        ctx.classificationData = {''};
                    end
                case 'detecdivpomegranate'
                    roiChannels = runtimeValidationRoiChannels(app);
                    if ~isempty(roiChannels)
                        ctx.channels = roiChannels;
                        ctx.roiChannels = roiChannels;
                    end
            end
        end

        function pkg = canonicalProcessorPackageName(app, pkg) %#ok<INUSD>
            raw = char(string(pkg));
            switch lower(strtrim(raw))
                case 'combinemultiplechannels'
                    pkg = 'combineMultipleChannels';
                case 'computemetrics'
                    pkg = 'computeMetrics';
                case 'computerls'
                    pkg = 'computeRLS';
                case 'computelineage'
                    pkg = 'computeLineage';
                case 'computemaxprojection'
                    pkg = 'computeMaxProjection';
                case 'basicobjecttracking'
                    pkg = 'basicObjectTracking';
                case 'formatindataseries'
                    pkg = 'formatInDataSeries';
                case 'fociburststats'
                    pkg = 'fociBurstStats';
                case 'detectviterbipombedivisionframe'
                    pkg = 'detectViterbiPombeDivisionFrame';
                case 'detecdivpomegranate'
                    pkg = 'detecdivPomegranate';
                case 'trackmotherlineageviterbi'
                    pkg = 'trackMotherLineageViterbi';
                case 'singlecelloscillations'
                    pkg = 'singleCellOscillations';
                otherwise
                    pkg = raw;
            end
        end

        function pkg = canonicalModulePackageName(app, nodeType, pkg)
            if strcmpi(char(string(nodeType)), 'processor')
                pkg = canonicalProcessorPackageName(app, pkg);
                return;
            end
            raw = char(string(pkg));
            key = lower(strtrim(raw));
            switch lower(char(string(nodeType)))
                case 'dataloader'
                    switch key
                        case 'dataloader'
                            pkg = 'dataLoader';
                        otherwise
                            pkg = raw;
                    end
                case {'roipattern','roiidentify'}
                    pkg = 'roiPattern';
                case 'roimanual'
                    pkg = 'roiManual';
                case 'roigrid'
                    pkg = 'roiGrid';
                case 'roitracked'
                    pkg = 'roiTracked';
                case 'roiextract'
                    pkg = 'roiExtract';
                case 'classifier'
                    switch key
                        case 'cellposesam'
                            pkg = 'cellposesam';
                        case 'cnn_lstm'
                            pkg = 'cnn_lstm';
                        case 'cnn'
                            pkg = 'cnn';
                        case 'celllatentmodel'
                            pkg = 'cellLatentModel';
                        case 'budmotherlinker'
                            pkg = 'budMotherLinker';
                        otherwise
                            pkg = raw;
                    end
                otherwise
                    pkg = raw;
            end
        end

        function keys = processorRuntimeKeys(app, pkg) %#ok<INUSD>
            switch pkg
                otherwise
                    keys = {};
            end
        end

        function keys = removeGlobalRuntimeKeys(app, keys) %#ok<INUSD>
            globalKeys = {'fovIndex','roiIndex','roiList','frames','channels','extractFrames','extractChannels','positionFilter','channelFilter','stackFilter'};
            keys = keys(~ismember(lower(keys), lower(globalKeys)));
        end

        function keys = filterParamsByAdvancedMode(app, node, keys)
            % Deprecated: keep all declared parameters visible in their
            % role-specific section.
        end

        function keys = easyParamKeys(app, node) %#ok<INUSD>
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
            switch nodeType
                case 'dataloader'
                    keys = {'path','positionFilter','channelFilter','stackFilter','label'};
                case {'roipattern','roiidentify'}
                    keys = {'channel','channelIndex','referenceFrame','threshold','fovIndex'};
                case 'roimanual'
                    keys = {'fovIndex'};
                case 'roigrid'
                    keys = {'gridCount'};
                case 'roitracked'
                    keys = {'fovIndex','roiIndex','channel','extractChannels'};
                case 'roiextract'
                    keys = {'executionMode','correctDrift','driftChannel','driftMethod','driftRefMode', ...
                        'driftSubpixel','driftMaxShift','scale','cropDrift','forceChannelNames'};
                case 'processor'
                    if strcmp(pkg, 'combinemultiplechannels')
                        keys = combineMultipleChannelsStaticKeysForNode(app, node);
                    elseif strcmp(pkg, 'computemetrics')
                        keys = computeMetricsStaticKeysForNode(app, node);
                    elseif strcmp(pkg, 'computerls')
                        keys = {'AverageFluoByDivision','StateDecoder','ExpectedDivisionPeriod','MinDivisionInterval','MinDivisionIntervalFactor','ArrestThreshold','DeathThreshold','ClogThreshold','EmptyThresholdNext','QCMinMeanMargin','QCMaxLowConfidenceFraction'};
                    elseif strcmp(pkg, 'computelineage')
                        keys = {'ArrestThreshold','DeathThreshold','ClogThreshold','EmptyThresholdNext'};
                    elseif strcmp(pkg, 'computemaxprojection')
                        keys = {'method','zstacks'};
                    elseif strcmp(pkg, 'basicobjecttracking')
                        keys = {'inputMode','coefDist','coefSize','coefIoU','maxRelativeDistance'};
                    elseif strcmp(pkg, 'trackmotherlineageviterbi')
                        keys = {'mode','tempConf','bottomSign','ratioMin','bonusSwitch'};
                    else
                        keys = {'channels','channel','frames'};
                    end
                case 'classifier'
                    keys = {'outputMode','outputType'};
                otherwise
                    keys = {};
            end
        end

        function out = valueToDisplay(app, v) %#ok<INUSD>
            if isMissingValue(app, v)
                out = '';
            elseif ischar(v)
                out = v;
            elseif isstring(v)
                try
                    v = v(~ismissing(v));
                catch
                end
                if isempty(v)
                    out = '';
                else
                    out = char(strjoin(v(:), ', '));
                end
            elseif isnumeric(v) || islogical(v)
                out = mat2str(v);
            elseif iscell(v) || isstruct(v)
                try
                    out = jsonencode(v);
                catch
                    out = '<complex>';
                end
            else
                try
                    out = char(string(v));
                catch
                    out = '<value>';
                end
            end
            if numel(out) > 120
                out = [out(1:117) '...'];
            end
        end

        function tf = isMissingValue(app, v) %#ok<INUSD>
            tf = false;
            try
                tf = any(ismissing(v(:)));
            catch
                tf = false;
            end
        end

        function v = normalizeMissingParamValue(app, v)
            if isMissingValue(app, v)
                if iscell(v)
                    try
                        keep = ~cellfun(@(x)isMissingValue(app, x), v);
                        v = v(keep);
                    catch
                        v = {};
                    end
                    if isempty(v)
                        v = '';
                    end
                elseif isstring(v)
                    try
                        v = v(~ismissing(v));
                    catch
                        v = strings(0,1);
                    end
                    if isempty(v)
                        v = "";
                    end
                else
                    v = '';
                end
            end
        end

        function txt = safeScalarText(app, v) %#ok<INUSD>
            txt = '';
            if isempty(v)
                return;
            end
            try
                if isMissingValue(app, v)
                    return;
                end
                vals = string(v(:));
                vals = vals(~ismissing(vals));
                if ~isempty(vals)
                    txt = char(vals(end));
                end
            catch
                txt = '';
            end
        end

        function out = paramValueToDisplay(app, node, key, value)
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            key = char(string(key));
            if strcmp(nodeType, 'custom') && (ischar(value) || (isstring(value) && isscalar(value)))
                out = char(value);
                return;
            end
            if iscell(value)
                out = choiceScalarText(app, value);
            else
                out = valueToDisplay(app, value);
            end
        end

        function label = friendlyParamLabel(app, node, key)
            keyText = char(string(key));
            keyLower = lower(keyText);
            if strcmpi(char(string(getField(app, node, 'type', ''))), 'classifier')
                pkgName = char(string(getField(app, node, 'pkg', '')));
                [spec, hasExecutionSpec] = classifierExecutionSpec(app, pkgName);
                if hasExecutionSpec
                    [declaredLabel, found] = classifierSpecMetadataValue(app, spec, 'labels', keyText);
                    if found && ~isempty(strtrim(char(string(declaredLabel))))
                        label = char(string(declaredLabel));
                        return;
                    end
                end
            end
            bgMatch = regexp(keyLower, '^mask(\d+)_backgroundlabel$', 'tokens', 'once');
            if ~isempty(bgMatch)
                label = sprintf('Mask %s background label', bgMatch{1});
                return;
            end
            scoreMatch = regexp(keyLower, '^mask(\d+)_scorelabel$', 'tokens', 'once');
            if ~isempty(scoreMatch)
                label = sprintf('Mask %s scored label', scoreMatch{1});
                return;
            end
            switch keyLower
                case 'mode'
                    label = 'Mode';
                case {'offset_channel1','offset_channel2','offset_channel3','offset_channel4','offset_channel5'}
                    label = char(regexprep(keyText, '^Offset_Channel', 'Division offset channel '));
                case 'gridcount'
                    label = 'Grid count';
                case 'extend'
                    label = 'Append existing ROI outputs';
                case 'correctdrift'
                    label = 'Correct drift';
                case 'driftchannel'
                    label = 'Drift channel';
                case 'driftmethod'
                    label = 'Drift method';
                case 'driftrefmode'
                    label = 'Drift reference';
                case 'driftsubpixel'
                    label = 'Subpixel drift';
                case 'driftmaxshift'
                    label = 'Max drift shift';
                case 'cropdrift'
                    label = 'Drift crop fraction';
                case 'forcechannelnames'
                    label = 'Force channel names';
                case 'scale'
                    label = 'Output scale';
                case 'outputtype'
                    label = 'Output resource';
                case 'outputname'
                    label = 'Segmentation output name';
                case 'probabilityoutputname'
                    label = 'Probability output name';
                case 'diameter'
                    label = 'Cell diameter';
                case 'min_size'
                    label = 'Minimum object size';
                case 'flow_threshold'
                    label = 'Flow threshold';
                case 'cell_prob_threshold'
                    label = 'Cell probability threshold';
                case 'outputmode'
                    label = 'Output resource';
                case 'cnnoutputname'
                    label = 'CNN output name';
                case 'executionenvironment'
                    label = 'Execution environment';
                otherwise
                    label = char(string(key));
            end
        end

        function txt = paramTooltip(app, node, key, scope) %#ok<INUSD>
            txt = '';
            if nargin < 4 || isempty(scope)
                scope = 'static';
            end
            nodeType = lower(char(string(getField(app, node, 'type', ''))));
            pkg = lower(char(string(getField(app, node, 'pkg', ''))));
            keyLower = lower(char(string(key)));
            if strcmp(nodeType, 'custom')
                switch keyLower
                    case 'entrypoint'
                        txt = 'MATLAB function name, optionally package.function. Context mode expects ctx = function(ctx).';
                    case 'codefolder'
                        txt = 'Folder containing the function or its +package. Relative to the saved pipeline folder, then the DetecDiv root. Must be accessible on the executing machine.';
                    case 'callmode'
                        txt = 'context: function receives ctx with user settings in ctx.params. arguments: ordered argumentsJson values, with returns assigned to outputPorts in order.';
                    case 'inputports'
                        txt = 'Required context fields separated by commas, e.g. roiList, masks. Optional type: features:tableSet. Connected modules share fields with the same name.';
                    case 'outputports'
                        txt = 'Output context fields separated by commas. In arguments mode, their order matches MATLAB return values, e.g. tables, files.';
                    case 'parametersjson'
                        txt = 'User parameter JSON object, e.g. {"threshold":0.5}. Available in ctx.params or via argument references. A run override replaces this object.';
                    case 'argumentsjson'
                        txt = 'Ordered JSON array, e.g. [{"context":"roiList"},{"param":"threshold"},"verbose",true]. Context references must be declared in inputPorts. No MATLAB expressions are evaluated.';
                end
                return;
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'classifier')
                pkgName = char(string(getField(app, node, 'pkg', '')));
                [spec, hasExecutionSpec] = classifierExecutionSpec(app, pkgName);
                if hasExecutionSpec
                    [declaredTip, found] = classifierSpecMetadataValue(app, spec, 'tips', key);
                    if found && ~isempty(strtrim(char(string(declaredTip))))
                        txt = char(string(declaredTip));
                        return;
                    end
                end
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'processor') && strcmp(pkg, 'computemetrics') && ...
                    ~isempty(regexp(keyLower, '^mask\d+_backgroundlabel$', 'once'))
                txt = ['Background index excluded from mask measurements. auto uses 0 when present; otherwise it uses 1 for U-Net/DeepLab/pixel-classifier maps. ' ...
                    'Force 0 for instance masks such as CellposeSAM, or 1 for old U-Net/classifier maps.'];
                return;
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'processor') && strcmp(pkg, 'computemetrics') && ...
                    ~isempty(regexp(keyLower, '^mask\d+_scorelabel$', 'once'))
                txt = ['Foreground index to measure. Use all, empty, or 0 to score every non-background label separately. ' ...
                    'Use a numeric label such as 2 to measure only that class; mask combinations are computed only when every mask in the pair has a numeric scored label.'];
                return;
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'processor') && strcmp(pkg, 'combinemultiplechannels')
                switch keyLower
                    case 'mode'
                        txt = 'Combination mode: additive RGB keeps the current color blending; subtraction and division produce a single grayscale channel.';
                        return;
                    case 'requiredchannelcount'
                        mode = combineMultipleChannelsModeForNode(app, node);
                        if any(strcmp(mode, {'subtraction','division'}))
                            txt = 'Arithmetic combination modes are fixed to exactly two selected channels.';
                        else
                            txt = 'Number of input channel bindings to expose. Use 0 for the legacy 5-slot mode.';
                        end
                        return;
                    case {'offset_channel1','offset_channel2','offset_channel3','offset_channel4','offset_channel5'}
                        txt = 'Division offset applied before the denominator is formed. Use 0 to keep the raw channel values.';
                        return;
                end
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'classifier') && strcmp(pkg, 'cellposesam')
                try
                    spec = cellposeExecutionSpec(app);
                    if isfield(spec, 'tips') && isfield(spec.tips, key)
                        txt = spec.tips.(key);
                    end
                catch
                    txt = '';
                end
                return;
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'classifier') && strcmp(pkg, 'sam31')
                try
                    spec = sam31ExecutionSpec(app);
                    if isfield(spec, 'tips') && isfield(spec.tips, key)
                        txt = spec.tips.(key);
                    end
                catch
                    txt = '';
                end
                return;
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'classifier') && strcmp(pkg, 'trackastra')
                try
                    spec = trackastraExecutionSpec(app);
                    if isfield(spec, 'tips') && isfield(spec.tips, key)
                        txt = spec.tips.(key);
                    end
                catch
                    txt = '';
                end
                return;
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'classifier') && strcmp(pkg, 'deeplab_pixel_classification')
                try
                    spec = deeplabPixelExecutionSpec(app);
                    if isfield(spec, 'tips') && isfield(spec.tips, key)
                        txt = spec.tips.(key);
                    end
                catch
                    txt = '';
                end
                return;
            end
            if strcmpi(scope, 'static') && strcmp(nodeType, 'classifier') && strcmp(pkg, 'cnn_lstm')
                try
                    spec = cnnLstmExecutionSpec(app);
                    if isfield(spec, 'tips') && isfield(spec.tips, key)
                        txt = spec.tips.(key);
                    end
                catch
                    txt = '';
                end
                return;
            end
        end

        function out = ternary(app, cond, ifTrue, ifFalse) %#ok<INUSD>
            if cond
                out = ifTrue;
            else
                out = ifFalse;
            end
        end

        function out = layoutSpan(app, first, last) %#ok<INUSD>
            if first == last
                out = first;
            else
                out = [first last];
            end
        end

        function refreshValidationReport(app, redraw)
            if nargin < 2 || isempty(redraw)
                redraw = true;
            end
            if app.OpeningPipeline || app.RuntimeInventoryMetadataOnly
                % An incomplete disk inventory is not evidence of invalid
                % bindings. Do not run classifier/path preflight on open.
                app.PipelineandRuncheckreportLabel.Text = ...
                    'Configuration loaded. Use Check build to verify inputs before execution.';
                if redraw, redrawGraph(app); end
                return;
            end
            d = openRuntimeProgress(app, 'Pipeline check', 'Checking pipeline...');
            cleanupObj = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
            updateRuntimeProgress(app, d, 'Building pipeline check model...');
            pipe = buildPipelineStruct(app);
            pipeForCheck = selectedPipelineStructForRun(app, pipe);
            updateRuntimeProgress(app, d, 'Collecting runtime binding context...');
            ctx = buildBindingValidationContext(app);
            if isempty(pipe.nodes)
                app.LastValidationOk = false;
                app.LastValidationReport = struct();
                if app.RuntimeModeUnlocked
                    setRuntimeStatus(app, pipelineSessionStatusText(app, false, pipe));
                else
                    setRuntimeStatus(app, sprintf('Template mode: runtime locked.\nClick New Run to configure execution.'));
                end
                app.PipelineandRuncheckreportLabel.Text = 'Click the grey block to add the first module.';
                if redraw
                    redrawGraph(app);
                end
                return;
            end

            try
                updateRuntimeProgress(app, d, 'Resolving pipeline bindings...');
                [pipeResolved, bindingResolution] = pipelineResolveBindings(pipeForCheck, ctx, ...
                    struct('allowGui', false, 'readOnlyModuleDiscovery', true, ...
                    'moduleCatalogue', [app.ModuleCatalogueItems.module]));
                updateRuntimeProgress(app, d, 'Validating pipeline...');
                [ok, report] = validatePipeline(pipeResolved, ctx, ...
                    struct('allowGui', false, 'readOnlyModuleDiscovery', true, ...
                    'moduleCatalogue', [app.ModuleCatalogueItems.module]));
                report.bindingResolution = bindingResolution;
            catch ME
                ok = false;
                report = struct('errors', {{ME.message}}, 'warnings', {{}}, 'solver', struct());
            end
            app.LastValidationOk = logical(ok);
            app.LastValidationReport = report;

            updateRuntimeProgress(app, d, 'Updating validation state...');
            app.Data.nodes = annotateNodeStatus(app, app.Data.nodes, report);
            refreshSelectedModuleTable(app);

            app.PipelineandRuncheckreportLabel.Text = formatValidationReport(app, ok, report);
            if app.RuntimeModeUnlocked
                setRuntimeStatus(app, pipelineSessionStatusText(app, ok, pipe));
            else
                setRuntimeStatus(app, sprintf('Template mode: runtime locked.\nClick New Run to configure execution.'));
            end
            if redraw
                updateRuntimeProgress(app, d, 'Redrawing pipeline graph...');
                redrawGraph(app);
            end
        end

        function CheckpipelineButtonPushed(app, event) %#ok<INUSD>
            discoverRuntimeRoiInventory(app, true);
            if ~app.RuntimeModeUnlocked
                [ok, report] = refreshValidationReportWithOutput(app);
                app.PipelineandRuncheckreportLabel.Text = formatValidationReport(app, ok, report);
                setRuntimeStatus(app, sprintf('Template mode: runtime locked.\nClick New Run to configure execution.'));
                return;
            end
            defaultsApplied = applyExistingProjectRuntimeDefaults(app);
            [ok, report] = refreshValidationReportWithOutput(app);
            runtimeIssues = validateRuntimeInputs(app);
            updateRuntimeInputStates(app);

            if isempty(runtimeIssues)
                if isempty(defaultsApplied)
                    setRuntimeStatus(app, sprintf('Runtime check: OK\nReady for execution.'));
                else
                    setRuntimeStatus(app, sprintf('Runtime check: OK\nDefaults from selected project: %s', strjoin(defaultsApplied, ', ')));
                end
            else
                prefix = 'Runtime check:';
                if ~isempty(defaultsApplied)
                    prefix = sprintf('Runtime check:\nDefaults from selected project: %s', strjoin(defaultsApplied, ', '));
                end
                setRuntimeStatus(app, [prefix newline '- ' strjoin(runtimeIssues, [newline '- '])]);
            end

            app.PipelineandRuncheckreportLabel.Text = formatValidationReport(app, ok, report);
        end

        function [ok, report] = refreshValidationReportWithOutput(app)
            if app.ModuleCatalogueFromCache
                refreshModuleCatalogue(app);
            end
            discoverRuntimeRoiInventory(app);
            pipe = buildPipelineStruct(app);
            pipeForCheck = selectedPipelineStructForRun(app, pipe);
            ctx = buildBindingValidationContext(app);
            if isempty(pipe.nodes)
                ok = false;
                report = struct('errors', {{'No module in pipeline.'}}, 'warnings', {{}}, 'solver', struct());
                app.LastValidationOk = false;
                app.LastValidationReport = report;
                refreshValidationReport(app);
                return;
            end
            try
                [pipeResolved, bindingResolution] = pipelineResolveBindings(pipeForCheck, ctx, ...
                    struct('allowGui', false, 'readOnlyModuleDiscovery', true, ...
                    'moduleCatalogue', [app.ModuleCatalogueItems.module]));
                [ok, report] = validatePipeline(pipeResolved, ctx, ...
                    struct('allowGui', false, 'readOnlyModuleDiscovery', true, ...
                    'moduleCatalogue', [app.ModuleCatalogueItems.module]));
                report.bindingResolution = bindingResolution;
            catch ME
                ok = false;
                report = struct('errors', {{ME.message}}, 'warnings', {{}}, 'solver', struct());
            end
            app.LastValidationOk = logical(ok);
            app.LastValidationReport = report;
            app.Data.nodes = annotateNodeStatus(app, app.Data.nodes, report);
            refreshSelectedModuleTable(app);
            if app.RuntimeModeUnlocked
                setRuntimeStatus(app, pipelineSessionStatusText(app, ok, pipe));
            else
                setRuntimeStatus(app, sprintf('Template mode: runtime locked.\nClick New Run to configure execution.'));
            end
            redrawGraph(app);
        end

        function txt = pipelineSessionStatusText(app, ok, pipe)
            if nargin < 3 || isempty(pipe)
                pipe = buildPipelineStruct(app);
            end
            if isempty(pipe) || ~isfield(pipe, 'nodes') || isempty(pipe.nodes)
                txt = 'Template mode - no module yet.';
                return;
            end

            quality = ternary(app, ok, 'valid', 'needs attention');
            base = sprintf('%d module(s), %d edge(s), %s.', numel(pipe.nodes), numel(pipe.edges), quality);

            if ~isempty(app.CurrentRun) && isa(app.CurrentRun, 'pipelineRun')
                runId = char(string(getField(app, app.CurrentRun, 'runId', 'run')));
                runStatus = effectiveCurrentRunStatus(app, app.CurrentRun);
                txt = sprintf('New run from existing run: %s (previous status: %s) - %s', runId, runStatus, base);
                return;
            end

            if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                projectName = char(string(getField(app, app.CurrentProject, 'strid', 'project')));
                if isempty(strtrim(projectName))
                    try
                        [~, projectName] = app.CurrentProject.getPath;
                    catch
                        projectName = 'project';
                    end
                end
                txt = sprintf('Template with project context: %s - %s', projectName, base);
                return;
            end

            txt = ['Independent template - ' base];
        end

        function status = effectiveCurrentRunStatus(app, runObj) %#ok<INUSD>
            status = 'unknown';
            try
                review = pipelineRunReview(runObj, 'Write', false);
                if isstruct(review) && isfield(review, 'status') && ~isempty(review.status)
                    status = char(string(review.status));
                    return;
                end
            catch
            end
            try
                if isprop(runObj, 'status') && ~isempty(runObj.status)
                    status = char(string(runObj.status));
                end
            catch
            end
        end

        function params = applyProjectDefaultOutputParams(app, params)
            %#ok<INUSD>
            % Keep empty output paths empty in pipeline templates. Absolute
            % project paths make templates machine-specific; the pipeline
            % runner resolves empty outputDir/outputFolder values from the
            % current runtime project context immediately before execution.
        end

        function issues = validateRuntimeInputs(app)
            issues = {};
            projectPath = strtrim(getRuntimeValue(app, 'projectPath'));
            rawDataPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
            rawOk = ~isempty(rawDataPath) && exist(rawDataPath, 'dir') == 7;
            startsFromProject = runtimeStartsFromExistingProject(app);
            startsFromClassifier = runtimeStartsFromClassifier(app);
            allowProjectCreation = ~startsFromProject && ~startsFromClassifier;
            projectPathOk = runtimeProjectPathUsable(app, projectPath, allowProjectCreation);
            loadedProjectOk = startsFromProject && hasLoadedRuntimeProject(app);
            projectOk = projectPathOk || loadedProjectOk;
            rawStartNodeIds = selectedRunNodeIdsByType(app, {'dataloader','roigrid','roiidentify','roimanual','roipattern','roiextract'});
            projectRawPath = '';
            projectHasImageSources = false;
            if startsFromProject && loadedProjectOk
                projectRawPath = projectSourcePath(app, app.CurrentProject);
                projectHasImageSources = projectHasFovImageSources(app, app.CurrentProject);
            end

            if ~startsFromClassifier && ~isempty(projectPath) && ~projectPathOk && ~loadedProjectOk
                if startsFromProject
                    issues{end+1} = ['Project path does not exist: ' projectPath]; %#ok<AGROW>
                    markRuntimeField(app, 'projectPath', 'missing', 'Project must be an existing folder or project JSON/legacy MAT file.');
                else
                    issues{end+1} = ['Project target cannot be created: ' projectPath]; %#ok<AGROW>
                    markRuntimeField(app, 'projectPath', 'missing', 'Project target parent folder must exist or be creatable.');
                end
            end

            if startsFromProject
                if ~projectOk
                    issues{end+1} = 'Read-from-existing-project mode requires a loaded shallow project.'; %#ok<AGROW>
                    markRuntimeField(app, 'projectPath', 'missing', 'Project must be an existing folder or project JSON/legacy MAT file.');
                end
                if loadedProjectOk
                    selectedFovs = parseRuntimeFovSelection(app, getRuntimeValue(app, 'fovs'));
                    nProjectFov = numel(app.CurrentProject.fov);
                    if ~isempty(selectedFovs) && any(selectedFovs > nProjectFov)
                        issues{end+1} = sprintf(['Selected FOV indices %s exceed the %d FOV(s) in the project. ' ...
                            'Numeric values are 1-based vector indices; use an explicit project FOV ID such as Pos11_3 to select by ID, or choose %s.'], ...
                            compactNumericSelectionText(app, selectedFovs), nProjectFov, boundedSelectionHint(app, nProjectFov)); %#ok<AGROW>
                        markRuntimeField(app, 'fovs', 'blocked', 'Numeric FOV selectors are vector indices; use an explicit project FOV ID to select by ID.');
                    end
                end
                if ~isempty(rawStartNodeIds) && ~projectHasImageSources
                    issues{end+1} = ['Read-from-existing-project mode cannot execute raw-image nodes because the selected project has no usable FOV image sources: ' ...
                        strjoin(rawStartNodeIds, ', ') '. Switch Input mode to "Parse raw images into project" and select the raw image folder, or relink/save the project FOV sources.']; %#ok<AGROW>
                    markRuntimeField(app, 'rawDataPath', 'blocked', 'Existing project has no usable FOV image sources; switch Input mode to Parse raw images into project.');
                elseif ~isempty(rawStartNodeIds) && isempty(projectRawPath)
                    issues{end+1} = ['Selected raw-start nodes need raw images, but no raw data link was found in the existing project: ' ...
                        strjoin(rawStartNodeIds, ', ') '. Relink/save the project raw data or switch Input mode to "Parse raw images into project".']; %#ok<AGROW>
                    markRuntimeField(app, 'rawDataPath', 'missing', 'Raw-start nodes will use the selected project raw data link; none was found.');
                end
            elseif startsFromClassifier
                if isempty(app.ExplicitRuntimeRoiList)
                    issues{end+1} = 'Classifier-attached ROI mode requires a source classifier containing ROI objects.'; %#ok<AGROW>
                    if ~app.RuntimeInputModeLocked
                        markRuntimeField(app, 'projectSource', 'missing', 'Select the source classifier whose attached ROIs will be processed.');
                    end
                    markRuntimeField(app, 'rois', 'missing', 'Classifier mode uses classifier.roi as runtime input.');
                else
                    channelIssues = attachedRoiModuleChannelIssues(app);
                    for ci = 1:numel(channelIssues)
                        issues{end+1} = channelIssues{ci}; %#ok<AGROW>
                    end
                    if ~isempty(channelIssues)
                        markRuntimeField(app, 'rois', 'blocked', 'Selected classifier ROIs do not expose every configured stored module input channel.');
                    end
                end
            elseif ~selectedRunHasNodeType(app, 'dataLoader')
                issues{end+1} = ['Input mode is "Parse raw images into project", but the selected run does not include a dataloader node. ' ...
                    'Switch Input mode to "Read from existing project", or include a dataloader in the selected pipeline run.']; %#ok<AGROW>
                markRuntimeField(app, 'rawDataPath', 'warning', ...
                    'Select a dataloader node in the run module list. The raw-data path can be set now.');
            elseif selectedRunHasNodeType(app, 'dataLoader')
                if isempty(rawDataPath)
                    issues{end+1} = 'Raw image folder is required when Input mode is Parse raw images into project.'; %#ok<AGROW>
                    markRuntimeField(app, 'rawDataPath', 'missing', 'Required when a dataloader run has no existing project input.');
                elseif ~isempty(rawDataPath) && ~rawOk
                    issues{end+1} = ['Raw data folder does not exist: ' rawDataPath]; %#ok<AGROW>
                    markRuntimeField(app, 'rawDataPath', 'missing', 'Raw data must be an existing folder.');
                elseif rawOk
                    if ~rawParserIsCurrent(app, rawDataPath)
                        parseRuntimeRawDataPath(app, rawDataPath);
                    end
                    if rawParserIsCurrent(app, rawDataPath) && rawParserHasNoChannels(app) && selectedRunNeedsChannels(app)
                        issues{end+1} = 'Raw parser did not detect any channel, but selected modules need image/ROI channels.'; %#ok<AGROW>
                        markRuntimeField(app, 'rawDataPath', 'warning', 'The raw parser is the channel authority in raw data mode. Check parser filters or raw metadata before running channel-dependent modules.');
                    elseif ~rawParserIsCurrent(app, rawDataPath)
                        msg = '';
                        if isfield(app.RuntimeParseInfo, 'message')
                            msg = char(string(app.RuntimeParseInfo.message));
                        end
                        issues{end+1} = ['Raw parser did not resolve FOV/frame/channel inventory before launch' ternary(app, isempty(msg), '.', [': ' msg])]; %#ok<AGROW>
                        markRuntimeField(app, 'rawDataPath', 'missing', 'Raw data inventory must be parsed before launching this run.');
                    end
                end
                if rawOk && rawParserIsCurrent(app, rawDataPath) && isfield(app.RuntimeParseInfo, 'fovCount') && app.RuntimeParseInfo.fovCount > 0
                    selectedFovs = parseRuntimeFovSelection(app, getRuntimeValue(app, 'fovs'));
                    nParsedFov = round(double(app.RuntimeParseInfo.fovCount));
                    if ~isempty(selectedFovs) && any(selectedFovs > nParsedFov)
                        issues{end+1} = sprintf('Selected FOVs request %s, but the raw parser detected only %d FOV(s). Change FOVs to %s or re-parse the raw folder.', ...
                            compactNumericSelectionText(app, selectedFovs), nParsedFov, boundedSelectionHint(app, nParsedFov)); %#ok<AGROW>
                        markRuntimeField(app, 'fovs', 'blocked', 'Selected FOVs exceed the parsed raw FOV count.');
                    end
                end
            end
            if strcmp(runtimeExecutionTarget(app), 'hub')
                hub = hubSettingsFromUi(app);
                if ~isfield(hub, 'baseUrl') || isempty(strtrim(char(string(hub.baseUrl))))
                    issues{end+1} = 'Hub URL is required when run target is Hub.'; %#ok<AGROW>
                end
                hasUserKey = isfield(hub, 'userKey') && ~isempty(strtrim(char(string(hub.userKey))));
                hasToken = isfield(hub, 'sessionToken') && ~isempty(strtrim(char(string(hub.sessionToken))));
                hasPassword = hasUserKey && ~isempty(strtrim(hubPasswordFromUi(app)));
                if ~(hasToken || hasUserKey || hasPassword)
                    issues{end+1} = 'Hub user key, password login, or session token is required when run target is Hub.'; %#ok<AGROW>
                end
                pathReport = hubPathPreflight(app, hub);
                if ~pathReport.ok
                    for i = 1:numel(pathReport.errors)
                        issues{end+1} = pathReport.errors{i}; %#ok<AGROW>
                    end
                end
            end
            [severity, message] = outputPolicyCompatibility(app);
            if strcmp(severity, 'warning')
                issues{end+1} = message; %#ok<AGROW>
            end
        end

        function txt = boundedSelectionHint(app, n) %#ok<INUSD>
            if isempty(n) || ~isfinite(double(n)) || double(n) <= 0
                txt = 'all';
                return;
            end
            n = round(double(n));
            if n <= 1
                txt = '1';
            else
                txt = sprintf('1:%d', n);
            end
        end

        function txt = formatRunPolicySummary(app)
            resumeLabel = char(string(app.ResumeoptionsDropDown.Value));
            outputPolicy = getRuntimeValue(app, 'outputPolicy');
            if isempty(outputPolicy)
                outputPolicy = 'skip';
            end
            switch char(string(outputPolicy))
                case 'skip'
                    policyLabel = 'skip existing outputs';
                case 'replace'
                    policyLabel = 'replace existing outputs';
                case 'upsert'
                    policyLabel = 'append/update existing outputs';
                case 'error'
                    policyLabel = 'error if outputs exist';
                otherwise
                    policyLabel = char(string(outputPolicy));
            end
            inputSourceLabel = 'read from existing project';
            if ~runtimeStartsFromExistingProject(app)
                inputSourceLabel = 'parse raw images into project';
            end
            roiExtractMode = '';
            if pipelineHasNodeType(app, 'roiExtract')
                switch char(string(outputPolicy))
                    case {'upsert','append'}
                        roiExtractMode = [newline 'Effective roiExtract mode: extend/append ROI H5 outputs.'];
                    case 'replace'
                        roiExtractMode = [newline 'Effective roiExtract mode: reset/replace ROI H5 outputs.'];
                    case 'skip'
                        roiExtractMode = [newline 'Effective roiExtract mode: skip completed outputs when possible.'];
                    case 'error'
                        roiExtractMode = [newline 'Effective roiExtract mode: fail if ROI outputs already exist.'];
                end
            end
            txt = ['Run policy:' newline ...
                '- Input mode: ' inputSourceLabel newline ...
                '- Target: ' runTargetLabel(app) newline ...
                '- Resume: ' resumeLabel newline ...
                '- Existing outputs: ' policyLabel roiExtractMode];
            [severity, message] = outputPolicyCompatibility(app);
            if strcmp(severity, 'warning')
                txt = [txt newline '- Warning: ' message];
            end
            txt = [txt newline '- Recommended: ' recommendedPolicySentence(app)];
        end

        function [confirmed, ctx] = confirmRunLaunch(app, ctx)
            confirmed = false;
            if nargin < 2 || ~isstruct(ctx) || isempty(ctx)
                ctx = struct();
            end
            d = [];
            try
                d = uiprogressdlg(app.UIFigure, 'Title', 'Preparing run confirmation', ...
                    'Message', 'Collecting effective run parameters...', ...
                    'Indeterminate', 'on', 'Cancelable', 'off');
                ddProgressGuard5 = onCleanup(@()detecdiv_close_progress(d)); %#ok<NASGU>
                drawnow nocallbacks;
            catch
            end
            try
                if ~isstruct(ctx) || isempty(fieldnames(ctx))
                    ctx = buildRunContext(app);
                end
                summaryText = formatRunConfirmationText(app, ctx);
            catch ME
                try, close(d); catch, end
                rethrow(ME);
            end
            try, close(d); catch, end

            try
                choice = uiconfirm(app.UIFigure, summaryText, 'Confirm pipeline run', ...
                    'Options', {'Run', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'question');
                confirmed = strcmp(choice, 'Run');
            catch
                answer = questdlg(summaryText, 'Confirm pipeline run', 'Run', 'Cancel', 'Cancel');
                confirmed = strcmp(answer, 'Run');
            end
        end

        function [confirmed, ctx] = confirmSmokeTestLaunch(app, ctx, smokeInfo)
            confirmed = false;
            if nargin < 2 || ~isstruct(ctx) || isempty(ctx)
                ctx = struct();
            end
            if nargin < 3 || ~isstruct(smokeInfo)
                smokeInfo = struct();
            end
            d = [];
            try
                d = uiprogressdlg(app.UIFigure, 'Title', 'Preparing smoke test confirmation', ...
                    'Message', 'Collecting effective smoke test parameters...', ...
                    'Indeterminate', 'on', 'Cancelable', 'off');
                ddProgressGuard6 = onCleanup(@()detecdiv_close_progress(d)); %#ok<NASGU>
                drawnow nocallbacks;
            catch
            end
            try
                summaryText = formatSmokeTestConfirmationText(app, ctx, smokeInfo);
            catch ME
                try, close(d); catch, end
                rethrow(ME);
            end
            try, close(d); catch, end

            try
                choice = uiconfirm(app.UIFigure, summaryText, 'Confirm smoke test', ...
                    'Options', {'Run smoke test', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'question');
                confirmed = strcmp(choice, 'Run smoke test');
            catch
                answer = questdlg(summaryText, 'Confirm smoke test', 'Run smoke test', 'Cancel', 'Cancel');
                confirmed = strcmp(answer, 'Run smoke test');
            end
        end

        function txt = formatRunConfirmationText(app, ctx)
            lines = {};
            lines{end+1} = 'Verify the effective run parameters before launching.';
            lines{end+1} = '';
            lines{end+1} = ['Run id: ' safeTextLocal(app, getField(app, ctx, 'runId', getRuntimeValue(app, 'runId')), '(auto)')];
            lines{end+1} = ['Target: ' runTargetLabel(app)];
            lines{end+1} = ['Intent: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','intent'}, getRuntimeValue(app, 'intent')), 'infer')];
            lines{end+1} = ['Input source: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','inputSource'}, inferRuntimeInputSource(app)), '')];
            lines{end+1} = ['Input mode: ' runtimeInputModeLabel(app)];
            lines{end+1} = ['Raw images: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','rawDataPath'}, getRuntimeValue(app, 'rawDataPath')), '(none)')];
            lines{end+1} = ['Output project: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','projectPath'}, getRuntimeValue(app, 'projectPath')), '(none)')];
            lines{end+1} = ['Output policy: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'io','globalExistingPolicy'}, getRuntimeValue(app, 'outputPolicy')), '(default)')];
            lines{end+1} = ['Resume policy: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','runPolicy'}, ''), '(default)')];
            lines{end+1} = ['Available FOVs: ' runtimeAvailableFovSummary(app)];
            lines{end+1} = '';
            lines{end+1} = ['FOVs: ' formatSelectionForConfirm(app, getNestedFieldLocal(app, ctx, {'sel','fovs'}, []))];
            lines{end+1} = ['Frames: ' formatSelectionForConfirm(app, getNestedFieldLocal(app, ctx, {'sel','frames'}, []))];
            lines{end+1} = ['ROIs: ' formatSelectionForConfirm(app, getNestedFieldLocal(app, ctx, {'sel','rois'}, []))];
            lines{end+1} = '';
            lines{end+1} = ['Nodes: ' formatRunNodeListForConfirm(app, getNestedFieldLocal(app, ctx, {'run','selectedNodes'}, {}))];
            nodeConstraints = formatNodeConstraintSummary(app, getNestedFieldLocal(app, ctx, {'run','nodeParams'}, struct()));
            lines = [lines nodeConstraints]; %#ok<AGROW>
            txt = strjoin(lines, newline);
        end

        function txt = formatSmokeTestConfirmationText(app, ctx, smokeInfo)
            lines = {};
            lines{end+1} = 'Verify the effective smoke test parameters before launching.';
            lines{end+1} = '';
            lines{end+1} = ['Smoke ROI: ' safeTextLocal(app, getField(app, smokeInfo, 'label', ''), '(unresolved)')];
            lines{end+1} = ['Smoke FOV: ' formatSelectionForConfirm(app, getNestedFieldLocal(app, ctx, {'sel','fovs'}, []))];
            lines{end+1} = ['Smoke frames: ' formatSelectionForConfirm(app, getNestedFieldLocal(app, ctx, {'sel','frames'}, []))];
            lines{end+1} = ['Smoke ROIs: ' formatSelectionForConfirm(app, getNestedFieldLocal(app, ctx, {'sel','rois'}, []))];
            roiId = safeTextLocal(app, getField(app, smokeInfo, 'roiId', ''), '');
            if ~isempty(strtrim(roiId))
                lines{end+1} = ['Smoke ROI id: ' roiId]; %#ok<AGROW>
            end
            lines{end+1} = '';
            lines{end+1} = 'Smoke output persistence: memory only; final ROI/H5/dataseries saves are disabled.';
            lines{end+1} = '';
            lines{end+1} = ['Run id: ' safeTextLocal(app, getField(app, ctx, 'runId', getRuntimeValue(app, 'runId')), '(auto)')];
            lines{end+1} = ['Intent: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','intent'}, getRuntimeValue(app, 'intent')), 'infer')];
            lines{end+1} = ['Input source: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','inputSource'}, inferRuntimeInputSource(app)), '')];
            lines{end+1} = ['Input mode: ' runtimeInputModeLabel(app)];
            lines{end+1} = ['Raw images: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','rawDataPath'}, getRuntimeValue(app, 'rawDataPath')), '(none)')];
            lines{end+1} = ['Project: ' safeTextLocal(app, getNestedFieldLocal(app, ctx, {'run','projectPath'}, getRuntimeValue(app, 'projectPath')), '(none)')];
            lines{end+1} = ['Available FOVs: ' runtimeAvailableFovSummary(app)];
            lines{end+1} = '';
            lines{end+1} = ['Nodes: ' formatRunNodeListForConfirm(app, getNestedFieldLocal(app, ctx, {'run','selectedNodes'}, {}))];
            nodeConstraints = formatNodeConstraintSummary(app, getNestedFieldLocal(app, ctx, {'run','nodeParams'}, struct()));
            lines = [lines nodeConstraints]; %#ok<AGROW>
            txt = strjoin(lines, newline);
        end

        function label = runtimeInputModeLabel(app)
            label = 'Read from existing project';
            try
                if runtimeStartsFromClassifier(app)
                    label = 'Use classifier attached ROIs';
                elseif ~runtimeStartsFromExistingProject(app)
                    label = 'Parse raw images into project';
                end
            catch
            end
        end

        function txt = runtimeAvailableFovSummary(app)
            txt = 'unresolved';
            try
                if runtimeStartsFromClassifier(app)
                    n = numel(app.ExplicitRuntimeRoiList);
                    if n > 0
                        suffix = '';
                        if n ~= 1
                            suffix = 's';
                        end
                        txt = sprintf('classifier ROI set (%d ROI%s)', n, suffix);
                    else
                        txt = 'classifier ROI set not attached';
                    end
                elseif runtimeStartsFromExistingProject(app)
                    if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                        n = numel(app.CurrentProject.fov);
                        txt = sprintf('1:%d from loaded project (%d total)', max(1, n), n);
                        if n == 0
                            txt = 'none in loaded project';
                        end
                    else
                        txt = 'no loaded project';
                    end
                else
                    if isfield(app.RuntimeParseInfo, 'fovCount') && app.RuntimeParseInfo.fovCount > 0
                        n = round(double(app.RuntimeParseInfo.fovCount));
                        txt = sprintf('1:%d from raw parser (%d total)', n, n);
                    else
                        txt = 'unresolved by raw parser';
                    end
                end
            catch
                txt = 'unresolved';
            end
        end

        function txt = formatSelectionForConfirm(app, value) %#ok<INUSD>
            if isempty(value)
                txt = 'all';
            elseif isnumeric(value)
                txt = compactNumericSelectionText(app, value);
            elseif iscell(value)
                txt = strjoin(cellstr(string(value(:))), ', ');
            else
                txt = char(string(value));
            end
            if isempty(strtrim(txt))
                txt = 'all';
            end
        end

        function txt = formatRunNodeListForConfirm(app, selectedIds)
            if isempty(selectedIds)
                txt = '(none)';
                return;
            end
            ids = cellstr(string(selectedIds(:)));
            labels = cell(1, numel(ids));
            for i = 1:numel(ids)
                labels{i} = ids{i};
                idx = find(strcmp({app.Data.nodes.id}, ids{i}), 1);
                if ~isempty(idx)
                    nodeType = char(string(getField(app, app.Data.nodes(idx), 'type', '')));
                    pkg = char(string(getField(app, app.Data.nodes(idx), 'pkg', '')));
                    if ~isempty(pkg)
                        labels{i} = sprintf('%s [%s/%s]', ids{i}, nodeType, pkg);
                    elseif ~isempty(nodeType)
                        labels{i} = sprintf('%s [%s]', ids{i}, nodeType);
                    end
                end
            end
            txt = strjoin(labels, ' -> ');
        end

        function lines = formatNodeConstraintSummary(app, nodeParams)
            lines = {};
            if ~isstruct(nodeParams) || isempty(fieldnames(nodeParams))
                lines{end+1} = 'Per-node constraints: none';
                return;
            end
            keysToShow = {'positionIdx','fovIndex','roiIndex','roiList','frames','frameRange','channelIdx','channels','extractFrames','extractChannels','useExistingProjectSources'};
            fn = fieldnames(nodeParams);
            details = {};
            for i = 1:numel(fn)
                params = nodeParams.(fn{i});
                if ~isstruct(params)
                    continue;
                end
                pairs = {};
                for k = 1:numel(keysToShow)
                    key = keysToShow{k};
                    if isfield(params, key) && ~isempty(params.(key))
                        pairs{end+1} = [key '=' formatParamValueForConfirm(app, params.(key))]; %#ok<AGROW>
                    end
                end
                if ~isempty(pairs)
                    details{end+1} = [fn{i} ': ' strjoin(pairs, ', ')]; %#ok<AGROW>
                end
            end
            if isempty(details)
                lines{end+1} = 'Per-node constraints: none';
            else
                lines{end+1} = 'Per-node constraints:';
                for i = 1:numel(details)
                    lines{end+1} = ['- ' details{i}]; %#ok<AGROW>
                end
            end
        end

        function txt = formatParamValueForConfirm(app, value)
            if isempty(value)
                txt = '[]';
            elseif isnumeric(value) || islogical(value)
                txt = formatSelectionForConfirm(app, value);
            elseif iscell(value)
                txt = strjoin(cellstr(string(value(:))), ',');
            elseif ischar(value) || isstring(value)
                txt = char(string(value));
            else
                try
                    txt = char(string(value));
                catch
                    txt = class(value);
                end
            end
            if numel(txt) > 120
                txt = [txt(1:117) '...'];
            end
        end

        function value = getNestedFieldLocal(app, s, path, defaultValue) %#ok<INUSD>
            value = defaultValue;
            try
                cur = s;
                for i = 1:numel(path)
                    key = path{i};
                    if ~isstruct(cur) || ~isfield(cur, key)
                        return;
                    end
                    cur = cur.(key);
                end
                value = cur;
            catch
                value = defaultValue;
            end
        end

        function txt = safeTextLocal(app, value, fallback) %#ok<INUSD>
            if nargin < 3
                fallback = '';
            end
            txt = fallback;
            try
                if ~isempty(value)
                    txt = char(string(value));
                end
            catch
                txt = fallback;
            end
        end

        function [severity, message] = outputPolicyCompatibility(app)
            severity = 'ok';
            message = '';
            resumeMode = char(string(app.ResumeoptionsDropDown.Value));
            outputPolicy = getRuntimeValue(app, 'outputPolicy');
            if isempty(outputPolicy)
                outputPolicy = recommendedOutputPolicy(app, resumeMode);
            end

            if strcmpi(resumeMode, 'Resume previous progress') && strcmp(outputPolicy, 'replace')
                severity = 'warning';
                message = 'Resume + replace is unusual: checkpoints are reused while existing outputs may be overwritten.';
            elseif strcmpi(resumeMode, 'Restart from scratch') && strcmp(outputPolicy, 'skip')
                severity = 'warning';
                message = 'Restart + skip is unusual: checkpoints are ignored but existing outputs are preserved.';
            elseif strcmpi(resumeMode, 'Restart from scratch') && strcmp(outputPolicy, 'upsert')
                severity = 'warning';
                message = 'Restart + append/update is only appropriate for controlled partial H5 updates.';
            end
        end

        function sentence = recommendedPolicySentence(app)
            resumeMode = char(string(app.ResumeoptionsDropDown.Value));
            if strcmpi(resumeMode, 'Restart from scratch')
                sentence = 'Restart from scratch -> Replace existing outputs for a clean rerun.';
            else
                sentence = 'Resume previous progress -> Skip existing outputs for the safest continuation.';
            end
        end

        function nodes = annotateNodeStatus(app, nodes, report) %#ok<INUSD>
            for i = 1:numel(nodes)
                nodes(i).status = 'OK';
            end
            if isstruct(report) && isfield(report, 'missingParams') && ~isempty(report.missingParams)
                for k = 1:numel(report.missingParams)
                    entry = report.missingParams{k};
                    idx = find(strcmp({nodes.id}, char(string(entry.node))), 1);
                    if ~isempty(idx)
                        nodes(idx).status = ['Missing: ' strjoin(entry.missing, ', ')];
                    end
                end
            end
            if isstruct(report) && isfield(report, 'deferredParams') && ~isempty(report.deferredParams)
                for k = 1:numel(report.deferredParams)
                    entry = report.deferredParams{k};
                    idx = find(strcmp({nodes.id}, char(string(entry.node))), 1);
                    if ~isempty(idx) && strcmp(nodes(idx).status, 'OK')
                        nodes(idx).status = ['Run: ' strjoin(entry.missing, ', ')];
                    end
                end
            end
        end

        function txt = formatValidationReport(app, ok, report) %#ok<INUSD>
            lines = {};
            if isstruct(report)
                if isfield(report, 'errors') && ~isempty(report.errors)
                    lines{end+1} = 'Errors:'; %#ok<AGROW>
                    for i = 1:numel(report.errors)
                        lines{end+1} = ['- ' char(string(report.errors{i}))]; %#ok<AGROW>
                    end
                end
                if isfield(report, 'warnings') && ~isempty(report.warnings)
                    lines{end+1} = ''; %#ok<AGROW>
                    lines{end+1} = 'Warnings:'; %#ok<AGROW>
                    for i = 1:min(numel(report.warnings), 12)
                        lines{end+1} = ['- ' char(string(report.warnings{i}))]; %#ok<AGROW>
                    end
                end
                if isfield(report, 'solver') && isstruct(report.solver) && isfield(report.solver, 'issues') && ~isempty(report.solver.issues)
                    severities = lower(string({report.solver.issues.severity}));
                    visibleIssues = report.solver.issues(severities ~= "info");
                    if ~isempty(visibleIssues)
                        lines{end+1} = ''; %#ok<AGROW>
                        lines{end+1} = sprintf('Solver issues: %d', numel(visibleIssues)); %#ok<AGROW>
                    end
                end
            end
            if isempty(lines)
                if ok
                    lines = {'No pipeline error.'};
                else
                    lines = {'Pipeline check needs attention.'};
                end
            end
            txt = strjoin(lines, newline);
        end

        function edges = validationReportResourceBindingEdges(app, report) %#ok<INUSD>
            edges = struct('from',{},'to',{},'fromPort',{},'toPort',{},'condition',{});
            if ~isstruct(report) || ~isfield(report, 'edges') || isempty(report.edges)
                return;
            end
            raw = report.edges;
            keep = false(size(raw));
            for i = 1:numel(raw)
                keep(i) = strcmpi(char(string(getField(app, raw(i), 'condition', ''))), 'resourceBinding');
            end
            edges = raw(keep);
        end

        function label = runTargetLabel(app)
            switch runtimeExecutionTarget(app)
                case 'hub'
                    label = 'Hub';
                case 'local_wsl'
                    label = 'Local / WSL';
                otherwise
                    label = 'Local / Windows';
            end
        end

        function pipe = buildPipelineStruct(app)
            pipe = struct();
            pipe.name = currentPipelineName(app);
            pipe.path = app.CurrentPipelinePath;
            pipe.nodes = sanitizeNodeParamsForPipeline(app, app.Data.nodes);
            if ~app.ViewerDefinitionOnly, pipe.nodes = applyRuntimeDerivedNodePolicies(app, pipe.nodes); end
            pipe.edges = app.Data.edges;
            pipe.branches = struct([]);
            if app.ViewerDefinitionOnly && isa(app.CurrentPipeline,'pipeline') && isvalid(app.CurrentPipeline)
                pipe.branches = app.CurrentPipeline.branches;
            end
        end

        function pipe = selectedPipelineStructForRun(app, pipe)
            selectedIds = selectedRunNodeIds(app);
            if isempty(selectedIds) || ~isfield(pipe, 'nodes') || isempty(pipe.nodes)
                return;
            end
            nodeIds = cellstr(string({pipe.nodes.id}));
            keep = ismember(nodeIds, selectedIds);
            if ~any(keep)
                return;
            end
            pipe.nodes = pipe.nodes(keep);
            if isfield(pipe, 'edges') && ~isempty(pipe.edges)
                edgeKeep = false(size(pipe.edges));
                for i = 1:numel(pipe.edges)
                    edgeKeep(i) = any(strcmp(selectedIds, char(string(pipe.edges(i).from)))) && ...
                        any(strcmp(selectedIds, char(string(pipe.edges(i).to))));
                end
                pipe.edges = pipe.edges(edgeKeep);
            end
        end

        function nodes = applyRuntimeDerivedNodePolicies(app, nodes)
            outputPolicy = getRuntimeValue(app, 'outputPolicy');
            if isempty(outputPolicy)
                outputPolicy = 'skip';
            end
            for i = 1:numel(nodes)
                if ~strcmpi(char(string(getField(app, nodes(i), 'type', ''))), 'roiExtract')
                    continue;
                end
                if ~isfield(nodes(i), 'params') || ~isstruct(nodes(i).params)
                    nodes(i).params = struct();
                end
                hasExtractChannels = isfield(nodes(i).params, 'extractChannels') && ~isempty(nodes(i).params.extractChannels);
                hasLegacyChannels = isfield(nodes(i).params, 'channels') && ~isempty(nodes(i).params.channels);
                if ~hasExtractChannels && hasLegacyChannels
                    nodes(i).params.extractChannels = nodes(i).params.channels;
                elseif ~hasExtractChannels
                    nodes(i).params.extractChannels = '@source';
                end
                if isfield(nodes(i).params, 'channels')
                    nodes(i).params = rmfield(nodes(i).params, 'channels');
                end
                switch char(string(outputPolicy))
                    case {'upsert','append'}
                        nodes(i).params.extend = true;
                    case 'replace'
                        nodes(i).params.extend = false;
                    otherwise
                        if isfield(nodes(i).params, 'extend')
                            nodes(i).params = rmfield(nodes(i).params, 'extend');
                        end
                end
            end
        end

        function pipe = buildPipelineTemplateStruct(app)
            pipe = struct();
            pipe.name = currentPipelineName(app);
            pipe.nodes = stripRuntimeParamsFromNodes(app, sanitizeNodeParamsForPipeline(app, app.Data.nodes));
            pipe.edges = app.Data.edges;
            pipe.branches = struct([]);
        end

        function nodes = sanitizeNodeParamsForPipeline(app, nodes)
            nodes = sanitizeClassifierNodeParams(app, nodes);
            nodes = sanitizeProcessorNodeParams(app, nodes);
            nodes = pipelineNormalizeNodes(nodes, 'persist');
        end

        function nodes = sanitizeProcessorNodeParams(app, nodes) %#ok<INUSD>
            for i = 1:numel(nodes)
                nodeType = lower(char(string(getField(app, nodes(i), 'type', ''))));
                if ~strcmp(nodeType, 'processor') || ~isfield(nodes(i), 'params') || ~isstruct(nodes(i).params)
                    continue;
                end
                if isfield(nodes(i).params, 'roiList')
                    nodes(i).params = rmfield(nodes(i).params, 'roiList');
                end
                pkg = lower(char(string(getField(app, nodes(i), 'pkg', ''))));
                if strcmp(pkg, 'trackmotherlineageviterbi') && ...
                        isfield(nodes(i).params, 'outputChannelName') && ~isempty(nodes(i).params.outputChannelName) && ...
                        isfield(nodes(i).params, 'outputName')
                    nodes(i).params = rmfield(nodes(i).params, 'outputName');
                end
                if ~strcmp(pkg, 'computerls')
                    continue;
                end
                if isfield(nodes(i).params, 'StateDecoder')
                    nodes(i).params.StateDecoder = choiceScalarText(app, nodes(i).params.StateDecoder);
                    if isempty(nodes(i).params.StateDecoder)
                        nodes(i).params.StateDecoder = 'off';
                    end
                end
            end
        end

        function nodes = sanitizeClassifierNodeParams(app, nodes) %#ok<INUSD>
            nodes = synchronizeCellLatentModelNodes(app, nodes);
            for i = 1:numel(nodes)
                nodeType = lower(char(string(getField(app, nodes(i), 'type', ''))));
                if ~strcmp(nodeType, 'classifier') || ~isfield(nodes(i), 'params') || ~isstruct(nodes(i).params)
                    continue;
                end
                pkgName = char(string(getField(app, nodes(i), 'pkg', '')));
                pkg = lower(pkgName);
                if isfield(nodes(i).params, 'roiList')
                    nodes(i).params = rmfield(nodes(i).params, 'roiList');
                end
                if isfield(nodes(i).params, 'trainingParam')
                    nodes(i).params = rmfield(nodes(i).params, 'trainingParam');
                end
                app.ensureCustomPackagePathForNode(nodes(i));
                [spec, hasExecutionSpec] = classifierExecutionSpec(app, pkgName);
                if hasExecutionSpec
                    nodes(i).params = applyClassifierExecutionDefaults(app, ...
                        nodes(i).params, pkgName, [], 'missing');
                    privateKeys = unique([spec.artifactKeys spec.environmentKeys], 'stable');
                    privateKeys = setdiff(privateKeys, classifierPipelineArtifactKeys(app, nodes(i)), 'stable');
                    privateKeys = privateKeys(isfield(nodes(i).params, privateKeys));
                    if ~isempty(privateKeys)
                        nodes(i).params = rmfield(nodes(i).params, privateKeys);
                    end
                end
                if strcmp(pkg, 'cellposesam')
                    nodes(i).params = applyCellposeExecutionDefaults(app, nodes(i).params, struct(), 'missing');
                    if isfield(nodes(i).params, 'outputType')
                        nodes(i).params.outputType = normalizeCellposeOutputTypeForPipeline(app, nodes(i).params.outputType);
                    end
                end
                if strcmp(pkg, 'trackastra')
                    nodes(i).params = applyTrackastraExecutionDefaults(app, nodes(i).params, struct(), 'missing');
                    artifactKeys = {'modelSource','customModelPath','checkpointPath','pythonExecutable'};
                    dropKeys = artifactKeys(isfield(nodes(i).params,artifactKeys));
                    if ~isempty(dropKeys)
                        nodes(i).params = rmfield(nodes(i).params,dropKeys);
                    end
                end
                if strcmp(pkg, 'deeplab_pixel_classification')
                    nodes(i).params = applyDeeplabPixelExecutionDefaults(app, nodes(i).params, struct(), 'missing');
                    if isfield(nodes(i).params, 'outputType')
                        nodes(i).params.outputType = normalizeDeeplabPixelOutputTypeForPipeline(app, nodes(i).params.outputType);
                    end
                    if isfield(nodes(i).params, 'executionEnvironment')
                        nodes(i).params.executionEnvironment = normalizeExecutionEnvironmentForPipeline(app, nodes(i).params.executionEnvironment);
                    end
                end
                if strcmp(pkg, 'cnn_lstm')
                    nodes(i).params = applyCnnLstmExecutionDefaults(app, nodes(i).params, struct(), 'missing');
                    if isfield(nodes(i).params, 'outputMode')
                        nodes(i).params.outputMode = normalizeCnnLstmOutputModeForPipeline(app, nodes(i).params.outputMode);
                    end
                    if isfield(nodes(i).params, 'executionEnvironment')
                        nodes(i).params.executionEnvironment = normalizeExecutionEnvironmentForPipeline(app, nodes(i).params.executionEnvironment);
                    end
                    keys = fieldnames(nodes(i).params);
                    drop = false(size(keys));
                    exact = {'train_CNN_classifier','compute_CNN_activations','train_LSTM_network', ...
                        'assemble_network','classifier_output','execution_environment','transfer_learning','tip'};
                    prefixes = {'CNN_','LSTM_','Format_'};
                    for kk = 1:numel(keys)
                        drop(kk) = any(strcmp(keys{kk}, exact)) || any(startsWith(keys{kk}, prefixes));
                    end
                    if any(drop)
                        nodes(i).params = rmfield(nodes(i).params, keys(drop));
                    end
                end
            end
        end

        function nodes = stripRuntimeParamsFromNodes(app, nodes) %#ok<INUSD>
            for i = 1:numel(nodes)
                if ~isfield(nodes(i), 'params') || ~isstruct(nodes(i).params)
                    continue;
                end
                nodeType = lower(char(string(getField(app, nodes(i), 'type', ''))));
                if strcmp(nodeType, 'dataloader')
                    dropKeys = {'path','positionIdx','channelIdx','frameRange','frames'};
                    nodes(i).params = removeNodeParamKeys(app, nodes(i).params, dropKeys);
                    continue;
                end
                if any(strcmp(nodeType, {'roiidentify','roipattern','roimanual','roigrid','roitracked','roiextract'}))
                    dropKeys = {'fovIndex','roiIndex','roiList','frames'};
                    if strcmp(nodeType, 'roiextract')
                        dropKeys{end+1} = 'extend'; %#ok<AGROW>
                    end
                    nodes(i).params = removeNodeParamKeys(app, nodes(i).params, dropKeys);
                end
            end
        end

        function params = removeNodeParamKeys(app, params, keys) %#ok<INUSD>
            for k = 1:numel(keys)
                if isfield(params, keys{k})
                    params = rmfield(params, keys{k});
                end
            end
        end

        function pipeObj = buildPipelineObject(app, targetPath, pipelineName)
            if nargin < 2 || isempty(targetPath)
                targetPath = app.CurrentPipelinePath;
            end
            if nargin < 3 || isempty(pipelineName)
                pipelineName = currentPipelineName(app);
            end
            name = normalizePipelineTemplateName(app, pipelineName, targetPath);
            pipeStruct = buildPipelineTemplateStruct(app);
            pipeObj = pipeline('', name, 1);
            pipeObj.setPath(targetPath, name);
            pipeObj.nodes = pipeStruct.nodes;
            pipeObj.edges = pipeStruct.edges;
            pipeObj.branches = pipeStruct.branches;
            pipeObj.description = ['Created from ' guiAppName(app)];
        end

        function pipeObj = buildExecutablePipelineObject(app, targetPath, ctx)
            targetPath = canonicalPipelineTemplatePath(app, targetPath);
            pipeObj = buildPipelineObject(app, targetPath);
            pipeStruct = selectedPipelineStructForRun(app, struct('nodes', pipeObj.nodes, 'edges', pipeObj.edges, 'branches', pipeObj.branches));
            pipeObj.nodes = pipeStruct.nodes;
            pipeObj.edges = pipeStruct.edges;
            if isfield(pipeStruct, 'branches')
                pipeObj.branches = pipeStruct.branches;
            end
            pipeObj.nodes = applyRunNodeParamsToNodes(app, pipeObj.nodes, ctx.run.nodeParams);
            pipeObj.nodes = applyRuntimeDerivedNodePolicies(app, pipeObj.nodes);
            pipeObj.nodes = sanitizeNodeParamsForPipeline(app, pipeObj.nodes);
            try
                [pipeObj, bindingResolution] = pipelineResolveBindings(pipeObj, ctx, ...
                    struct('allowGui', false, 'readOnlyModuleDiscovery', true, ...
                    'moduleCatalogue', [app.ModuleCatalogueItems.module]));
                app.Data.lastBindingResolution = bindingResolution;
            catch
            end
        end

        function nodes = applyRunNodeParamsToNodes(app, nodes, nodeParams)
            if ~isstruct(nodeParams)
                return;
            end
            nodes = pipelineApplyRunNodeParams(nodes, nodeParams);
            for i = 1:numel(nodes)
                nodeId = char(string(getField(app, nodes(i), 'id', '')));
                key = matlab.lang.makeValidName(nodeId);
                if ~isfield(nodeParams, key) || ~isstruct(nodeParams.(key))
                    continue;
                end
                if ~isfield(nodes(i), 'params') || ~isstruct(nodes(i).params)
                    nodes(i).params = struct();
                end
                runParams = nodeParams.(key);
                nodes(i) = applyCustomPackagePatchToNode(app, nodes(i), runParams);
            end
        end

        function name = currentPipelineName(app)
            name = defaultPipelineTemplateName(app);
            if ~isempty(app.CurrentPipeline) && isa(app.CurrentPipeline, 'pipeline') && ~isempty(app.CurrentPipeline.strid)
                name = char(string(app.CurrentPipeline.strid));
                if strcmp(name, guiAppName(app))
                    name = defaultPipelineTemplateName(app);
                end
            elseif ~isempty(app.CurrentPipelinePath)
                [~, name] = fileparts(app.CurrentPipelinePath);
                if isempty(name) || strcmp(name, guiAppName(app))
                    name = defaultPipelineTemplateName(app);
                end
            end
        end

        function name = normalizePipelineTemplateName(app, name, targetPath)
            name = strtrim(char(string(name)));
            [~, baseName, extName] = fileparts(name);
            if ~isempty(extName)
                name = baseName;
            end
            if isempty(name) || strcmpi(name, 'pipeline') || strcmp(name, guiAppName(app))
                if nargin >= 3 && ~isempty(targetPath)
                    [~, folderName] = fileparts(stripTrailingFilesep(app, targetPath));
                    if ~isempty(folderName)
                        name = folderName;
                    end
                end
            end
            if isempty(name) || strcmp(name, guiAppName(app))
                name = defaultPipelineTemplateName(app);
            end
        end

        function out = stripTrailingFilesep(app, in) %#ok<INUSD>
            out = char(string(in));
            while numel(out) > 1 && (endsWith(out, filesep) || endsWith(out, '/') || endsWith(out, '\'))
                out = out(1:end-1);
            end
        end

        function markPipelineDirty(app, isDirty)
            if nargin < 2
                isDirty = true;
            end
            app.IsPipelineDirty = logical(isDirty);
            updatePipelineWindowTitle(app);
            updatePipelineRunStatusBar(app);
            updateDocumentationMenuState(app);
        end

        function markRunDirty(app, isDirty)
            if nargin < 2
                isDirty = true;
            end
            app.IsRunDirty = logical(isDirty);
            updatePipelineWindowTitle(app);
            updatePipelineRunStatusBar(app);
            updateDocumentationMenuState(app);
        end

        function updatePipelineWindowTitle(app)
            pipeName = strtrim(currentPipelineName(app));
            if isempty(pipeName)
                pipeName = defaultPipelineTemplateName(app);
            end
            pipeSuffix = '';
            if app.IsPipelineDirty
                pipeSuffix = '*';
            end
            if app.ViewerDefinitionOnly
                app.UIFigure.Name = sprintf('Pipeline Designer - %s%s',pipeName,pipeSuffix); return;
            end
            runName = currentRunDisplayName(app);
            runSuffix = '';
            if app.IsRunDirty || app.CurrentRunIsSeed
                runSuffix = '*';
            end
            app.UIFigure.Name = sprintf('%s - Pipeline %s%s | Run %s%s', ...
                guiAppName(app), pipeName, pipeSuffix, runName, runSuffix);
        end

        function updatePipelineRunStatusBar(app, detail)
            if nargin >= 2 && ~isempty(detail)
                app.LastStatusDetail = char(string(detail));
            end
            try
                txt = formatPipelineRunStatusBar(app);
                app.RuninformationhereLabel.Text = txt;
            catch
            end
        end

        function txt = formatPipelineRunStatusBar(app)
            pipeName = strtrim(currentPipelineName(app));
            if isempty(pipeName)
                pipeName = defaultPipelineTemplateName(app);
            end
            pipeSuffix = '';
            if app.IsPipelineDirty
                pipeSuffix = '*';
            end
            runName = currentRunDisplayName(app);
            runSuffix = '';
            if app.IsRunDirty || app.CurrentRunIsSeed
                runSuffix = '*';
            end
            txt = sprintf('Pipeline: %s%s | Run: %s%s', pipeName, pipeSuffix, runName, runSuffix);
            detail = strtrim(char(string(app.LastStatusDetail)));
            if ~isempty(detail)
                txt = [txt newline detail];
            end
        end

        function runName = currentRunDisplayName(app)
            runName = '(none)';
            try
                if app.CurrentRunIsSeed || app.IsRunDirty
                    runId = runtimeRunIdFromUi(app);
                    if ~isempty(strtrim(runId))
                        runName = runId;
                        return;
                    end
                end
            catch
            end
            try
                if ~isempty(app.CurrentRun) && isa(app.CurrentRun, 'pipelineRun') && ...
                        ~isempty(app.CurrentRun.runId)
                    runName = char(string(app.CurrentRun.runId));
                    return;
                end
            catch
            end
            try
                runId = runtimeRunIdFromUi(app);
                if ~isempty(strtrim(runId))
                    runName = runId;
                end
            catch
            end
        end

        function name = guiAppName(app) %#ok<INUSD>
            name = 'pipelineGUI2';
        end

        function name = defaultPipelineTemplateName(app) %#ok<INUSD>
            name = 'pipelineTemplate';
        end

        function ok = savePipelineInteractive(app, forceAs)
            ok = false;
            if nargin < 2
                forceAs = false;
            end
            targetPath = app.CurrentPipelinePath;
            targetName = currentPipelineName(app);
            if ~forceAs && (app.CurrentPipelineIsRunSnapshot || isRunSnapshotPipelinePath(app, targetPath))
                choice = uiconfirm(app.UIFigure, ...
                    ['This pipeline was loaded from a run snapshot.' newline ...
                     'Use Save as... to create a normal editable template.'], ...
                    'Run snapshot pipeline', 'Options', {'Save as...','Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 2);
                if strcmp(choice, 'Cancel')
                    return;
                end
                forceAs = true;
                targetPath = '';
            end
            if forceAs || isempty(targetPath)
                [file, pth] = uiputfile('*.json', 'Save pipeline template', defaultSavePipelineDialogPath(app));
                if isequal(file, 0)
                    return;
                end
                [targetPath, targetName] = resolvePipelineSaveTarget(app, file, pth);
            end
            d = [];
            try
                try
                    d = uiprogressdlg(app.UIFigure, 'Title', 'Save pipeline', ...
                        'Message', 'Preparing pipeline template save...', ...
                        'Value', 0.05, 'Cancelable', 'off');
                    ddProgressGuard7 = onCleanup(@()detecdiv_close_progress(d)); %#ok<NASGU>
                    drawnow nocallbacks;
                    drawnow limitrate nocallbacks;
                catch
                    d = [];
                end
                oldWorkspaceVar = app.CurrentPipelineWorkspaceVar;
                try
                    if ~isempty(d) && isvalid(d)
                        d.Message = 'Building pipeline template object...';
                        d.Value = 0.25;
                        drawnow limitrate nocallbacks;
                    end
                catch
                end
                pipeObj = buildPipelineObject(app, targetPath, targetName);
                try
                    if ~isempty(d) && isvalid(d)
                        d.Message = 'Writing pipeline JSON and module artifacts...';
                        d.Value = 0.55;
                        drawnow limitrate nocallbacks;
                    end
                catch
                end
                pipelineSave(pipeObj);
                try
                    if ~isempty(d) && isvalid(d)
                        d.Message = 'Updating pipeline state...';
                        d.Value = 0.85;
                        drawnow limitrate nocallbacks;
                    end
                catch
                end
                app.CurrentPipeline = pipeObj;
                app.CurrentPipelinePath = pipeObj.path;
                app.CurrentPipelineIsRunSnapshot = false;
                assignCurrentPipelineToWorkspace(app, pipeObj, oldWorkspaceVar);
                addRecentPipelinePath(app, fullfile(pipeObj.path, 'pipeline.json'));
                markPipelineDirty(app, false);
                ok = true;
                suffix = '';
                if ~isempty(app.CurrentPipelineWorkspaceVar)
                    suffix = [' | workspace: ' app.CurrentPipelineWorkspaceVar];
                end
                msg = ['Pipeline saved: ' fullfile(pipeObj.path, 'pipeline.json') suffix ' | template only'];
                try
                    if ~isempty(d) && isvalid(d)
                        d.Message = 'Pipeline template saved.';
                        d.Value = 1;
                        drawnow limitrate nocallbacks;
                    end
                catch
                end
                setRuntimeStatus(app, msg);
            catch ME
                try, closeProgressDialog(app, d); catch, end
                uialert(app.UIFigure, ME.message, 'Save pipeline', 'Icon', 'error');
                return;
            end
            try, closeProgressDialog(app, d); catch, end
        end

        function dialogPath = defaultSavePipelineDialogPath(app)
            baseDir = pwd;
            baseName = currentPipelineName(app);
            if ~isempty(app.CurrentPipelinePath)
                if isfolder(app.CurrentPipelinePath)
                    baseDir = fileparts(stripTrailingFilesep(app, app.CurrentPipelinePath));
                    if isempty(baseDir)
                        baseDir = app.CurrentPipelinePath;
                    end
                    [~, folderName] = fileparts(stripTrailingFilesep(app, app.CurrentPipelinePath));
                    if ~isempty(folderName)
                        baseName = folderName;
                    end
                else
                    baseDir = fileparts(app.CurrentPipelinePath);
                end
            end
            baseName = normalizePipelineTemplateName(app, baseName, app.CurrentPipelinePath);
            dialogPath = fullfile(baseDir, [baseName '.json']);
        end

        function [targetPath, targetName] = resolvePipelineSaveTarget(app, file, pth)
            file = char(string(file));
            pth = char(string(pth));
            [~, baseName, extName] = fileparts(file);
            if isempty(baseName)
                baseName = file;
            end

            if strcmpi(extName, '.json') && strcmpi(baseName, 'pipeline')
                targetPath = pth;
                [~, targetName] = fileparts(stripTrailingFilesep(app, pth));
            else
                targetName = baseName;
                targetPath = fullfile(pth, targetName);
            end
            targetName = normalizePipelineTemplateName(app, targetName, targetPath);
        end

        function assignCurrentPipelineToWorkspace(app, pipeObj, oldWorkspaceVar)
            if app.ViewerDefinitionOnly, return; end
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end
            if nargin < 3
                oldWorkspaceVar = '';
            end
            varName = '';
            try
                varName = char(string(pipeObj.strid));
            catch
            end
            if strcmp(varName, guiAppName(app))
                varName = defaultPipelineTemplateName(app);
            end
            if isempty(strtrim(varName))
                try
                    [~, varName] = fileparts(pipeObj.path);
                catch
                end
            end
            if isempty(strtrim(varName))
                varName = 'pipelineObj';
            end
            varName = matlab.lang.makeValidName(varName);
            try
                clearReplacedPipelineWorkspaceVar(app, oldWorkspaceVar, varName);
                assignin('base', varName, pipeObj);
                app.CurrentPipelineWorkspaceVar = varName;
                clearGenericPipelineWorkspaceAliases(app, varName);
                clearInternalPipelineAlias(app, varName);
            catch
            end
        end

        function clearReplacedPipelineWorkspaceVar(app, oldWorkspaceVar, newWorkspaceVar) %#ok<INUSD>
            oldWorkspaceVar = char(string(oldWorkspaceVar));
            newWorkspaceVar = char(string(newWorkspaceVar));
            if isempty(oldWorkspaceVar) || strcmp(oldWorkspaceVar, newWorkspaceVar)
                return;
            end
            if ~isvarname(oldWorkspaceVar)
                return;
            end
            try
                if evalin('base', ['exist(''' oldWorkspaceVar ''',''var'')']) && ...
                        evalin('base', ['isa(' oldWorkspaceVar ',''pipeline'')'])
                    evalin('base', ['clear ' oldWorkspaceVar]);
                end
            catch
            end
        end

        function clearGenericPipelineWorkspaceAliases(app, canonicalVarName)
            genericNames = {guiAppName(app), defaultPipelineTemplateName(app), 'pipelineObj'};
            for i = 1:numel(genericNames)
                varName = char(string(genericNames{i}));
                if isempty(varName) || strcmp(varName, canonicalVarName) || ~isvarname(varName)
                    continue;
                end
                try
                    if evalin('base', ['exist(''' varName ''',''var'')']) && ...
                            evalin('base', ['isa(' varName ',''pipeline'')'])
                        evalin('base', ['clear ' varName]);
                    end
                catch
                end
            end
        end

        function clearInternalPipelineAlias(app, canonicalVarName) %#ok<INUSD>
            if strcmp(char(string(canonicalVarName)), guiAppName(app))
                return;
            end
            try
                if evalin('base', ['exist(''' guiAppName(app) ''',''var'')'])
                    isPipe = evalin('base', ['isa(' guiAppName(app) ',''pipeline'')']);
                    if isPipe
                        evalin('base', ['clear ' guiAppName(app)]);
                    end
                end
            catch
            end
        end

        function addRecentPipelinePath(app, pipelineFile)
            pipelineFile = normalizeRecentPipelinePath(app, pipelineFile);
            if isempty(pipelineFile)
                return;
            end
            paths = recentPipelinePaths(app, true);
            paths = paths(~strcmpi(paths, pipelineFile));
            paths = [{pipelineFile} paths];
            maxCount = 10;
            if numel(paths) > maxCount
                paths = paths(1:maxCount);
            end
            try
                setpref('DetecDiv', 'pipeline2RecentPipelines', paths);
            catch
            end
            updateRecentPipelinesMenu(app);
        end

        function paths = recentPipelinePaths(app, keepMissing) %#ok<INUSD>
            if nargin < 2
                keepMissing = false;
            end
            paths = {};
            try
                paths = getpref('DetecDiv', 'pipeline2RecentPipelines', {});
            catch
                paths = {};
            end
            if ischar(paths) || (isstring(paths) && isscalar(paths))
                paths = {char(string(paths))};
            elseif isstring(paths)
                paths = cellstr(paths(:))';
            elseif ~iscell(paths)
                paths = {};
            end
            normalized = {};
            for i = 1:numel(paths)
                p = normalizeRecentPipelinePath(app, paths{i});
                if isempty(p)
                    continue;
                end
                if app.OpeningPipeline || keepMissing || exist(p, 'file') == 2
                    normalized{end+1} = p; %#ok<AGROW>
                end
            end
            paths = unique(normalized, 'stable');
            if ~keepMissing && ~app.OpeningPipeline
                try
                    setpref('DetecDiv', 'pipeline2RecentPipelines', paths);
                catch
                end
            end
        end

        function pipelineFile = normalizeRecentPipelinePath(app, pipelineFile) %#ok<INUSD>
            pipelineFile = strtrim(char(string(pipelineFile)));
            if isempty(pipelineFile)
                return;
            end
            if app.OpeningPipeline
                % The recent-files menu is only a list of saved references.
                % Never probe or canonicalize unrelated network paths just
                % to display it. The selected file is checked when opened.
                [~, ~, ext] = fileparts(pipelineFile);
                if isempty(ext)
                    pipelineFile = fullfile(pipelineFile, 'pipeline.json');
                end
                return;
            end
            try
                if exist(pipelineFile, 'dir') == 7
                    pipelineFile = fullfile(pipelineFile, 'pipeline.json');
                end
                if exist(pipelineFile, 'file') == 2
                    pipelineFile = char(java.io.File(pipelineFile).getCanonicalPath());
                end
            catch
            end
        end

        function updateRecentPipelinesMenu(app)
            if isempty(app.LoadrecentpipelineMenu) || ~isvalid(app.LoadrecentpipelineMenu)
                return;
            end
            try
                delete(app.LoadrecentpipelineMenu.Children);
            catch
            end
            paths = recentPipelinePaths(app, false);
            if isempty(paths)
                item = uimenu(app.LoadrecentpipelineMenu, 'Text', '(No recent pipelines)');
                item.Enable = 'off';
                app.LoadrecentpipelineMenu.Enable = 'off';
                return;
            end
            app.LoadrecentpipelineMenu.Enable = 'on';
            for i = 1:numel(paths)
                label = recentPipelineMenuLabel(app, paths{i});
                uimenu(app.LoadrecentpipelineMenu, 'Text', label, ...
                    'MenuSelectedFcn', @(~,~)loadRecentPipeline(app, paths{i}));
            end
            uimenu(app.LoadrecentpipelineMenu, 'Text', 'Clear recent pipelines', ...
                'Separator', 'on', 'MenuSelectedFcn', @(~,~)clearRecentPipelines(app));
        end

        function label = recentPipelineMenuLabel(app, pipelineFile) %#ok<INUSD>
            pipelineFile = char(string(pipelineFile));
            [folder, file, ext] = fileparts(pipelineFile);
            [parent, folderName] = fileparts(folder);
            [~, parentName] = fileparts(parent);
            label = [folderName filesep file ext];
            if ~isempty(parentName)
                label = [parentName filesep label];
            end
        end

        function loadRecentPipeline(app, pipelineFile)
            pipelineFile = normalizeRecentPipelinePath(app, pipelineFile);
            if isempty(pipelineFile) || exist(pipelineFile, 'file') ~= 2
                updateRecentPipelinesMenu(app);
                uialert(app.UIFigure, 'This recent pipeline file no longer exists.', 'Load recent pipeline', 'Icon', 'warning');
                return;
            end
            try
                [pipeObj, msg] = pipelineLoad(pipelineFile);
                if isempty(pipeObj)
                    error('pipeline2:PipelineLoadFailed', '%s', msg);
                end
                loadPipelineFromObject(app, pipeObj);
                addRecentPipelinePath(app, pipelineFile);
                if ~isempty(app.CurrentPipelineWorkspaceVar)
                    setRuntimeStatus(app, ['Pipeline loaded in workspace: ' app.CurrentPipelineWorkspaceVar]);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Load recent pipeline', 'Icon', 'error');
            end
        end

        function clearRecentPipelines(app)
            try
                setpref('DetecDiv', 'pipeline2RecentPipelines', {});
            catch
            end
            updateRecentPipelinesMenu(app);
        end

        function loadPipelineFromObject(app, pipeObj, restoreLatestRun)
            if nargin < 3 || isempty(restoreLatestRun)
                restoreLatestRun = false;
            end
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline')
                return;
            end
            nodes = pipeObj.nodes;
            d = openRuntimeProgress(app, 'Open pipeline', 'Loading pipeline modules...');
            progressCleanup = onCleanup(@()closeRuntimeProgress(app, d)); %#ok<NASGU>
            if isempty(nodes)
                nodes = struct([]);
            end
            nodes = normalizeLoadedNodeLayouts(app, nodes);
            nodes = synchronizeCellLatentModelNodes(app, nodes);
            for i = 1:numel(nodes)
                reportPipelineOpenStage(app, sprintf('Preparing module %d of %d: %s...', ...
                    i, numel(nodes), char(string(getField(app, nodes(i), 'id', 'module')))));
                if ~isfield(nodes(i), 'layout') || isempty(nodes(i).layout)
                    nodes(i).layout = [i 1 1 1];
                end
                if ~isfield(nodes(i), 'name') || isempty(nodes(i).name)
                    nodes(i).name = nodes(i).id;
                end
                if ~isfield(nodes(i), 'uiAdvanced') || isempty(nodes(i).uiAdvanced)
                    nodes(i).uiAdvanced = false;
                end
                try
                    nodes(i).contract = pipelineNodeContract(nodes(i));
                    nodes(i).inputs = portNames(app, nodes(i).contract, 'in');
                    nodes(i).outputs = portNames(app, nodes(i).contract, 'out');
                catch
                end
            end
            app.Data.nodes = nodes;
            app.Data.edges = pipeObj.edges;
            if isempty(app.Data.edges)
                rebuildEdgesFromLayout(app);
            end
            app.CurrentPipeline = pipeObj;
            app.CurrentPipelinePath = pipeObj.path;
            app.CurrentPipelineIsRunSnapshot = isRunSnapshotPipelinePath(app, pipeObj.path);
            assignCurrentPipelineToWorkspace(app, pipeObj);
            app.CurrentRun = [];
            app.CurrentRunPath = '';
            app.CurrentRunIsSeed = false;
            app.CurrentRunSourceId = '';
            app.RuntimeNodeParams = struct();
            markPipelineDirty(app, false);
            markRunDirty(app, false);
            if ~logical(restoreLatestRun)
                setRuntimeExecutionTarget(app, 'local');
            end
            app.SelectedNodeIndex = ternary(app, isempty(nodes), NaN, 1);
            app.NodeCounter = inferNodeCounter(app, nodes);
            refreshSelectedModuleTable(app, false);
            latestRunApplied = false;
            if logical(restoreLatestRun)
                latestRunApplied = applyLatestRunForCurrentPipeline(app, false);
            end
            refreshAfterModelChange(app, false);
            if latestRunApplied && ~isempty(app.CurrentRun) && isa(app.CurrentRun, 'pipelineRun')
                try
                    setRuntimeStatus(app, pipelineSessionStatusText(app, app.LastValidationOk, buildPipelineStruct(app)));
                catch
                end
            else
                setRuntimeModeUnlocked(app, false);
            end
        end

        function applied = applyLatestRunForCurrentPipeline(app, refreshUi)
            if nargin < 2 || isempty(refreshUi)
                refreshUi = true;
            end
            applied = false;
            if ~isempty(app.CurrentRun) || isempty(app.CurrentPipeline) || ~isa(app.CurrentPipeline, 'pipeline') || ...
                    isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            try
                runs = app.CurrentPipeline.findDependentRuns(app.CurrentProject);
            catch
                runs = pipelineRun.empty;
            end
            runObj = latestPipelineRun(app, runs);
            if isempty(runObj)
                return;
            end
            loadRunIntoUi(app, runObj, refreshUi);
            applied = true;
            if logical(refreshUi)
                try
                    setRuntimeStatus(app, pipelineSessionStatusText(app, app.LastValidationOk, buildPipelineStruct(app)));
                catch
                end
            end
        end

        function runObj = latestPipelineRun(app, runs)
            runObj = [];
            if isempty(runs)
                return;
            end
            bestScore = -inf;
            bestIdx = 0;
            for i = 1:numel(runs)
                score = pipelineRunTimestampScore(app, runs(i), i);
                if score >= bestScore
                    bestScore = score;
                    bestIdx = i;
                end
            end
            if bestIdx > 0
                runObj = runs(bestIdx);
            end
        end

        function score = pipelineRunTimestampScore(app, runObj, fallbackIndex) %#ok<INUSD>
            score = double(fallbackIndex);
            txt = '';
            try
                if isprop(runObj, 'updatedAt') && ~isempty(runObj.updatedAt)
                    txt = char(string(runObj.updatedAt));
                elseif isprop(runObj, 'createdAt') && ~isempty(runObj.createdAt)
                    txt = char(string(runObj.createdAt));
                end
            catch
                txt = '';
            end
            if isempty(txt)
                return;
            end
            try
                dt = datetime(txt);
                if ~isnat(dt)
                    score = datenum(dt);
                    return;
                end
            catch
            end
            try
                score = datenum(txt);
            catch
            end
        end

        function nodes = normalizeLoadedNodeLayouts(app, nodes) %#ok<INUSD>
            if isempty(nodes)
                return;
            end
            n = numel(nodes);
            cols = nan(1, n);
            rows = nan(1, n);
            for i = 1:n
                if isfield(nodes(i), 'layout') && numel(nodes(i).layout) >= 2 && all(isfinite(double(nodes(i).layout(1:2))))
                    cols(i) = round(double(nodes(i).layout(1)));
                    rows(i) = round(double(nodes(i).layout(2)));
                else
                    cols(i) = i;
                    rows(i) = 1;
                end
            end

            % Older detecdiv-created templates used pixel-like [x y w h]
            % values such as [10 10 20 10]. The graph renderer now expects
            % compact symbolic columns/rows.
            needsCompact = max(cols, [], 'omitnan') > n + 2 || max(rows, [], 'omitnan') > n + 2 || any(cols < 1) || any(rows < 1);
            if ~needsCompact
                return;
            end

            uniqueCols = unique(cols(isfinite(cols)), 'stable');
            uniqueRows = unique(rows(isfinite(rows)), 'stable');
            for i = 1:n
                col = find(uniqueCols == cols(i), 1, 'first');
                row = find(uniqueRows == rows(i), 1, 'first');
                if isempty(col), col = i; end
                if isempty(row), row = 1; end
                nodes(i).layout = [col row 1 1];
            end
        end

        function n = inferNodeCounter(app, nodes) %#ok<INUSD>
            n = numel(nodes);
            for i = 1:numel(nodes)
                toks = regexp(char(string(nodes(i).id)), '_(\d+)$', 'tokens', 'once');
                if ~isempty(toks)
                    n = max(n, str2double(toks{1}));
                end
            end
        end

        function ctx = buildRunContext(app, progressDlg)
            if nargin < 2
                progressDlg = [];
            end
            ctx = struct();
            ctx.allowGUI = false;
            ctx.interactive = false;
            ctx.dryRun = false;

            if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                ctx.shallow = app.CurrentProject;
                ctx.shallowObj = app.CurrentProject;
            end

            ctx.run = struct();
            updateRunSaveProgress(app, progressDlg, 'Preparing run: collecting selected modules...', 0.10);
            ctx.run.selectedNodes = selectedRunNodeIds(app);
            updateRunSaveProgress(app, progressDlg, 'Preparing run: collecting node parameters...', 0.16);
            ctx.run.nodeParams = buildRunNodeParams(app);
            ctx.run.inputSourceMode = getRuntimeValue(app, 'inputSourceMode');
            if runtimeStartsFromClassifier(app)
                ctx.run.classifierSource = app.RuntimeClassifierSourceRef;
            end
            intent = getRuntimeValue(app, 'intent');
            if isempty(strtrim(intent))
                intent = 'infer';
            end
            ctx.run.intent = intent;
            ctx.run.classifierIntent = intent;
            ctx.run.runPolicy = resumeModeToRunPolicy(app, app.ResumeoptionsDropDown.Value);
            ctx.run.resume = strcmp(ctx.run.runPolicy, 'resume');
            ctx.run.gpuPolicy = lower(char(string(app.ExecutionDropDown.Value)));
            if strcmp(ctx.run.gpuPolicy, 'auto')
                ctx.run.gpuPolicy = 'module_default';
            end
            ctx.run.executionTarget = runtimeExecutionTarget(app);
            hubExecution = strcmpi(ctx.run.executionTarget, 'hub');
            if strcmpi(ctx.run.executionTarget, 'local_wsl')
                ctx.exec = struct('python', struct('backend', 'wsl'));
            elseif strcmpi(ctx.run.executionTarget, 'local')
                ctx.exec = struct('python', struct('backend', 'local'));
            end
            updateRunSaveProgress(app, progressDlg, 'Preparing run: resolving input source...', 0.24);
            if hubExecution
                ctx.run.inputSource = inferRuntimeInputSourceFast(app);
            else
                ctx.run.inputSource = inferRuntimeInputSource(app);
            end
            ctx.run.control = buildRunControlPolicy(app, ctx.run.executionTarget);
            if strcmp(ctx.run.executionTarget, 'hub')
                ctx.hub = hubSettingsFromUi(app);
            end

            ctx.io = struct();
            policy = getRuntimeValue(app, 'outputPolicy');
            if isempty(policy)
                policy = recommendedOutputPolicy(app, app.ResumeoptionsDropDown.Value);
            end
            ctx.io.existingPolicy = policy;
            ctx.io.globalExistingPolicy = policy;
            ctx.io.cachePolicy = 'auto';
            ctx.store = struct('cacheMode', 'auto');

            ctx.sel = struct();
            ctx.sel.fovs = parseRuntimeFovSelection(app, getRuntimeValue(app, 'fovs'));
            ctx.sel.frames = parseIndexSelection(app, getRuntimeValue(app, 'frames'));
            ctx.sel.rois = parseLooseSelection(app, getRuntimeValue(app, 'rois'));
            ctx.run.fovIndex = ctx.sel.fovs;
            ctx.run.frames = ctx.sel.frames;
            ctx.run.rois = ctx.sel.rois;
            ctx.run.fovSelection = struct( ...
                'requestedText', getRuntimeValue(app, 'fovs'), ...
                'resolvedIndices', runtimeFovSelectionIndices(app, ctx.sel.fovs), ...
                'resolvedIds', {runtimeFovSelectionIds(app, ctx.sel.fovs)}, ...
                'semantics', 'Numeric values are 1-based indices; explicit project FOV IDs resolve by exact ID.');

            if runtimeStartsFromExistingProject(app) && ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                try
                    projectFovs = app.CurrentProject.fov;
                    fovIdx = ctx.sel.fovs;
                    if isempty(fovIdx)
                        fovIdx = 1:numel(projectFovs);
                    else
                        fovIdx = fovIdx(fovIdx >= 1 & fovIdx <= numel(projectFovs));
                    end
                    if ~isempty(fovIdx)
                        ctx.fovList = projectFovs(fovIdx);
                        ctx.images = ctx.fovList;
                    end
                catch
                end
            end

            updateRunSaveProgress(app, progressDlg, 'Preparing run: scanning available channels...', 0.36);
            sourceRuntimeChannels = runtimeSourceChannels(app);
            if hubExecution
                updateRunSaveProgress(app, progressDlg, 'Preparing run: deferring ROI inventory to Hub worker...', 0.40);
                roiRuntimeChannels = {};
            else
                roiRuntimeChannels = runtimeValidationRoiChannels(app);
            end
            availableRuntimeChannels = roiRuntimeChannels;
            if isempty(availableRuntimeChannels)
                availableRuntimeChannels = sourceRuntimeChannels;
            end
            ctx.run.availableChannels = availableRuntimeChannels;
            if ~isempty(roiRuntimeChannels)
                ctx.roiChannels = roiRuntimeChannels;
            end
            if ~isfield(ctx, 'channels') && ~isempty(sourceRuntimeChannels)
                ctx.channels = sourceRuntimeChannels;
            end
            if hubExecution
                ctx.run.runtimeInventoryMode = 'server_resolved';
                updateRunSaveProgress(app, progressDlg, 'Preparing run: scanning lightweight dataseries inventory...', 0.48);
                dataSeriesNames = runtimeDataSeriesNames(app);
                if ~isempty(dataSeriesNames)
                    ctx.dataSeriesNames = dataSeriesNames;
                    ctx.dataSeries = dataSeriesNames;
                end
            else
                updateRunSaveProgress(app, progressDlg, 'Preparing run: scanning runtime dataseries...', 0.48);
                dataSeriesNames = runtimeDataSeriesNames(app);
                if ~isempty(dataSeriesNames)
                    ctx.dataSeriesNames = dataSeriesNames;
                    ctx.dataSeries = dataSeriesNames;
                end
                updateRunSaveProgress(app, progressDlg, 'Preparing run: selecting ROI handles...', 0.58);
                roiList = runtimeSelectedRois(app);
                if isempty(roiList) && ~isempty(app.ExplicitRuntimeRoiList)
                    roiList = app.ExplicitRuntimeRoiList;
                    if isfield(ctx, 'sel') && isstruct(ctx.sel) && isfield(ctx.sel, 'rois') && ~isempty(ctx.sel.rois)
                        try
                            idx = ctx.sel.rois;
                            if isnumeric(idx)
                                idx = idx(idx >= 1 & idx <= numel(roiList));
                                roiList = roiList(idx);
                            end
                        catch
                        end
                    end
                end
                if ~isempty(roiList)
                    ctx.roiList = roiList;
                    ctx.rois = roiList;
                end
                runtimeMasks = runtimeMaskChoices(app);
                if ~isempty(runtimeMasks)
                    ctx.masks = runtimeMasks;
                end
            end

            updateRunSaveProgress(app, progressDlg, 'Preparing run: resolving project and raw paths...', 0.68);
            rawDataPath = effectiveRuntimeRawDataPath(app);
            projectPath = getRuntimeValue(app, 'projectPath');
            if runtimeStartsFromClassifier(app)
                rawDataPath = '';
                projectPath = '';
            end
            useProjectSources = runtimeShouldUseExistingProjectSources(app);
            ctx.run.rawDataPath = rawDataPath;
            ctx.run.projectPath = projectPath;
            ctx.io.rawDataPath = rawDataPath;
            ctx.io.projectPath = projectPath;
            ctx.rawDataPath = rawDataPath;
            ctx.projectPath = projectPath;
            ctx.run.useExistingProjectSources = useProjectSources;
            ctx.dataLoader = struct('path', rawDataPath, 'useExistingProjectSources', useProjectSources);
            inventory = runtimeInventorySnapshot(app, rawDataPath);
            if isstruct(inventory) && isfield(inventory, 'ok') && logical(inventory.ok)
                ctx.run.runtimeInventory = inventory;
                ctx.runtimeInventory = inventory;
            end

            updateRunSaveProgress(app, progressDlg, 'Preparing run: building pipeline snapshot...', 0.80);
            ctx.pipelineSpec = buildPipelineStruct(app);
            ctx.pipelineRef = buildPipelineRef(app);
            ctx.targetRef = buildTargetRef(app);
            updateRunSaveProgress(app, progressDlg, 'Preparing run: context ready.', 0.90);
        end

        function rawDataPath = effectiveRuntimeRawDataPath(app)
            if runtimeStartsFromClassifier(app)
                rawDataPath = '';
                return;
            end
            rawDataPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
            if ~runtimeStartsFromExistingProject(app)
                return;
            end
            if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                projectRawPath = projectSourcePath(app, app.CurrentProject);
                if ~isempty(projectRawPath)
                    rawDataPath = projectRawPath;
                end
            end
        end

        function [snapshotPath, templateSnapshotPath] = saveLocalProcessPipelineSnapshot(app, pipeObj, runObj)
            if isempty(pipeObj) || ~isa(pipeObj, 'pipeline') || ...
                    isempty(runObj) || ~isa(runObj, 'pipelineRun')
                error('pipeline2:LocalSnapshotInput', ...
                    'A pipeline and pipelineRun are required for a local process snapshot.');
            end
            runPath = char(string(runObj.path));
            if isempty(runPath)
                error('pipeline2:LocalSnapshotPath', ...
                    'The run path is empty; cannot create a local process snapshot.');
            end
            snapshotDir = fullfile(runPath, 'pipeline_snapshot');
            if exist(snapshotDir, 'dir') ~= 7
                mkdir(snapshotDir);
            end
            pipeObj.setPath(snapshotDir);
            pipelineSave(pipeObj, 'Artifacts', false, 'Audit', false);
            snapshotPath = fullfile(snapshotDir, 'pipeline.json');
            if exist(snapshotPath, 'file') ~= 2
                error('pipeline2:LocalSnapshotSave', ...
                    'Local process pipeline snapshot was not created: %s', snapshotPath);
            end

            % The executable snapshot may contain only the modules selected
            % for this run. Keep a second, complete template snapshot so a
            % later "New run from this" can restore every template module
            % and merely uncheck the modules omitted by the previous run.
            templateSnapshotDir = fullfile(runPath, 'pipeline_template_snapshot');
            if exist(templateSnapshotDir, 'dir') ~= 7
                mkdir(templateSnapshotDir);
            end
            templateObj = buildPipelineObject(app, templateSnapshotDir, currentPipelineName(app));
            pipelineSave(templateObj, 'Artifacts', false, 'Audit', false);
            templateSnapshotPath = fullfile(templateSnapshotDir, 'pipeline.json');
            if exist(templateSnapshotPath, 'file') ~= 2
                error('pipeline2:LocalTemplateSnapshotSave', ...
                    'Complete pipeline template snapshot was not created: %s', templateSnapshotPath);
            end
        end

        function info = runtimeInventorySnapshot(app, rawDataPath)
            if nargin < 2
                rawDataPath = effectiveRuntimeRawDataPath(app);
            end
            rawDataPath = strtrim(char(string(rawDataPath)));
            info = struct('path', rawDataPath, 'ok', true, 'message', '', ...
                'datatype', '', 'fovCount', 0, 'fovNames', {{}}, 'maxFrame', [], 'channels', {{}});

            if isfield(app.RuntimeParseInfo, 'ok') && logical(app.RuntimeParseInfo.ok)
                parsed = normalizeRuntimeInventoryInfo(app, app.RuntimeParseInfo);
                if isempty(rawDataPath) || isempty(parsed.path) || strcmp(char(string(parsed.path)), rawDataPath)
                    info = parsed;
                    if isempty(info.path)
                        info.path = rawDataPath;
                    end
                    return;
                end
            end

            if runtimeStartsFromExistingProject(app) && ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                projectInfo = summarizeExistingProjectRuntime(app);
                if isfield(projectInfo, 'fovCount') && projectInfo.fovCount > 0
                    info.fovCount = projectInfo.fovCount;
                    info.fovNames = projectInfo.fovNames;
                end
                if isfield(projectInfo, 'maxFrame') && ~isempty(projectInfo.maxFrame)
                    info.maxFrame = projectInfo.maxFrame;
                end
            else
                selectedFovs = parseRuntimeFovSelection(app, getRuntimeValue(app, 'fovs'));
                if ~isempty(selectedFovs)
                    info.fovCount = max(selectedFovs(:));
                    info.fovNames = arrayfun(@(k)sprintf('FOV %d', k), 1:info.fovCount, 'UniformOutput', false);
                end
                selectedFrames = parseIndexSelection(app, getRuntimeValue(app, 'frames'));
                if ~isempty(selectedFrames)
                    info.maxFrame = max(selectedFrames(:));
                end
            end

            info.channels = runtimeConcreteChannels(app);
            if isempty(info.channels) && info.fovCount <= 0 && isempty(info.maxFrame)
                info.ok = false;
                info.message = 'No runtime inventory available at save time.';
            end
        end

        function tf = runtimeShouldUseExistingProjectSources(app)
            tf = false;
            if ~runtimeStartsFromExistingProject(app)
                return;
            end
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            tf = projectHasFovImageSources(app, app.CurrentProject);
        end

        function source = inferRuntimeInputSource(app)
            if runtimeStartsFromClassifier(app)
                source = 'classifier attached rois';
                return;
            end
            rawStartNodeIds = selectedRunNodeIdsByType(app, {'dataloader','roigrid','roiidentify','roimanual','roipattern','roiextract'});
            if ~runtimeStartsFromExistingProject(app)
                source = 'pipeline start (dataloader)';
                return;
            end
            if ~isempty(rawStartNodeIds) && ~runtimeShouldUseExistingProjectSources(app)
                source = 'pipeline start (dataloader)';
                return;
            end
            source = 'existing project fovs';
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            try
                dataSeriesNames = runtimeDataSeriesNames(app);
                if ~isempty(dataSeriesNames)
                    source = 'existing dataseries';
                    return;
                end
            catch
            end
            try
                if ~isempty(runtimeMaskChoices(app))
                    source = 'existing masks';
                    return;
                end
            catch
            end
            try
                if projectHasAnyRoi(app, app.CurrentProject)
                    source = 'existing rois';
                    return;
                end
            catch
            end
            source = 'existing project fovs';
        end

        function source = inferRuntimeInputSourceFast(app)
            if runtimeStartsFromClassifier(app)
                source = 'classifier attached rois';
                return;
            end
            rawStartNodeIds = selectedRunNodeIdsByType(app, {'dataloader','roigrid','roiidentify','roimanual','roipattern','roiextract'});
            if ~runtimeStartsFromExistingProject(app)
                source = 'pipeline start (dataloader)';
                return;
            end
            if ~isempty(rawStartNodeIds) && ~runtimeShouldUseExistingProjectSources(app)
                source = 'pipeline start (dataloader)';
                return;
            end
            source = 'existing project fovs';
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            try
                if projectHasAnyRoi(app, app.CurrentProject)
                    source = 'existing rois';
                end
            catch
            end
        end

        function control = buildRunControlPolicy(app, executionTarget) %#ok<INUSD>
            target = lower(char(string(executionTarget)));
            if isempty(target)
                target = 'local';
            end
            control = struct();
            control.backend = target;
            control.cancelPolicy = 'cooperative';
            control.resumePolicy = resumeModeToRunPolicy(app, app.ResumeoptionsDropDown.Value);
            control.progressGranularity = 'roi';
            control.safeStopPoint = 'between_rois';
            switch target
                case 'hub'
                    control.cancelMode = 'hub_job_cancel';
                    control.cancelEndpoint = '/pipeline-runs/{job_id}/cancel';
                    control.statusEndpoint = '/pipeline-runs/{job_id}';
                case 'local_wsl'
                    control.cancelMode = 'file_token';
                    control.cancelTokenFile = '';
                    control.pythonBackend = 'wsl';
                otherwise
                    control.cancelMode = 'file_token';
                    control.cancelTokenFile = '';
                    control.pythonBackend = 'local';
            end
        end

        function tf = projectHasAnyRoi(app, shallowObj) %#ok<INUSD>
            tf = false;
            try
                for i = 1:numel(shallowObj.fov)
                    if isprop(shallowObj.fov(i), 'roi') && ~isempty(shallowObj.fov(i).roi)
                        tf = true;
                        return;
                    end
                end
            catch
            end
        end

        function nodeParams = buildRunNodeParams(app)
            nodeParams = struct();
            fn = fieldnames(app.RuntimeNodeParams);
            for i = 1:numel(fn)
                params = app.RuntimeNodeParams.(fn{i});
                if ~isstruct(params)
                    continue;
                end
                nodeId = runtimeKeyToNodeId(app, fn{i});
                if isempty(nodeId)
                    continue;
                end
                params = stripGlobalRoiPolicyParams(app, nodeId, params);
                params = stripTemplatePlaceholderRuntimeParams(app, nodeId, params);
                nodeParams.(matlab.lang.makeValidName(nodeId)) = params;
            end

            nodeParams = pipelineSnapshotRoiPatternOverrides(app.Data.nodes, nodeParams);
            rawDataPath = effectiveRuntimeRawDataPath(app);
            useProjectSources = runtimeShouldUseExistingProjectSources(app);
            for i = 1:numel(app.Data.nodes)
                if strcmpi(char(string(getField(app, app.Data.nodes(i), 'type', ''))), 'dataLoader')
                    nodeId = char(string(app.Data.nodes(i).id));
                    key = matlab.lang.makeValidName(nodeId);
                    if ~isfield(nodeParams, key) || ~isstruct(nodeParams.(key))
                        nodeParams.(key) = struct();
                    end
                    nodeParams.(key).useExistingProjectSources = useProjectSources;
                    if ~isempty(rawDataPath)
                        nodeParams.(key).path = rawDataPath;
                    end
                    % A template can retain a channelFilter from an older
                    % project (for example "Channel 1").  When this run
                    % parses raw data, such a stale filter would both hide
                    % the parsed inventory and become the symbolic source
                    % exposed to roiExtract.  Clear it only when it matches
                    % none of the channels just parsed; valid user filters
                    % remain untouched.
                    if dataLoaderFilterIsStaleForRawInventory(app, app.Data.nodes(i), nodeParams.(key))
                        nodeParams.(key).channelFilter = {};
                    end
                end
            end
            for i = 1:numel(app.Data.nodes)
                node = app.Data.nodes(i);
                nodeId = char(string(getField(app, node, 'id', '')));
                if isempty(nodeId)
                    continue;
                end
                key = matlab.lang.makeValidName(nodeId);
                patch = customPackagePatchFromNode(app, node);
                if isempty(fieldnames(patch))
                    continue;
                end
                if ~isfield(nodeParams, key) || ~isstruct(nodeParams.(key))
                    nodeParams.(key) = struct();
                end
                nodeParams.(key) = mergeStructOverride(app, nodeParams.(key), patch);
            end
        end

        function tf = dataLoaderFilterIsStaleForRawInventory(app, node, runtimeParams)
            tf = false;
            if runtimeStartsFromExistingProject(app) || runtimeStartsFromClassifier(app)
                return;
            end
            available = runtimeSourceChannels(app);
            if isempty(available)
                return;
            end

            params = getField(app, node, 'params', struct());
            if ~isstruct(params)
                params = struct();
            end
            if isstruct(runtimeParams) && isfield(runtimeParams, 'channelFilter')
                params.channelFilter = runtimeParams.channelFilter;
            end
            if ~isfield(params, 'channelFilter') || isempty(params.channelFilter)
                return;
            end

            configured = normalizeChannelSelectionValue(app, params.channelFilter);
            configured = configured(~cellfun(@(x)isempty(strtrim(char(string(x)))), configured));
            configured = configured(~ismember(lower(string(configured)), ["all", "*", ":", "auto", "@source"]));
            if isempty(configured)
                return;
            end

            availableLower = lower(string(available));
            for i = 1:numel(configured)
                value = lower(strtrim(char(string(configured{i}))));
                if any(availableLower == value)
                    continue;
                end
                % Wildcard filters are legitimate parser selectors; their
                % validity is resolved by the dataloader itself.
                if contains(value, {'*', '$', '#'})
                    continue;
                end
                tf = true;
                return;
            end
        end

        function patch = customPackagePatchFromNode(app, node) %#ok<INUSD>
            patch = struct();
            keys = {'customPackageRoot','customPackageDir','customPackageLoadedAt'};
            for i = 1:numel(keys)
                key = keys{i};
                if isstruct(node) && isfield(node, key) && ~isempty(node.(key))
                    patch.(key) = node.(key);
                end
            end
            p = getField(app, node, 'params', struct());
            if isstruct(p)
                for i = 1:numel(keys)
                    key = keys{i};
                    if ~isfield(patch, key) && isfield(p, key) && ~isempty(p.(key))
                        patch.(key) = p.(key);
                    end
                end
            end
        end

        function params = stripTemplatePlaceholderRuntimeParams(app, nodeId, params)
            if ~isstruct(params)
                return;
            end
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx) || ~isfield(app.Data.nodes(idx), 'params') || ~isstruct(app.Data.nodes(idx).params)
                return;
            end
            templateParams = app.Data.nodes(idx).params;
            keys = fieldnames(params);
            for i = 1:numel(keys)
                key = keys{i};
                if ~isfield(templateParams, key) || isempty(templateParams.(key))
                    continue;
                end
                % Placeholder cleanup concerns textual channel bindings.
                % Image patches and other structured run settings are not
                % empty bindings, even when they cannot be rendered as text.
                value = params.(key);
                if ~(ischar(value) || isstring(value) || iscellstr(value))
                    continue;
                end
                if isZStackPlaceholderBinding(app, params.(key)) && ~isSymbolicStoredBinding(app, templateParams.(key))
                    params = rmfield(params, key);
                end
            end
        end

        function params = stripGlobalRoiPolicyParams(app, nodeId, params)
            if ~isstruct(params)
                return;
            end
            idx = find(strcmp({app.Data.nodes.id}, char(string(nodeId))), 1);
            if isempty(idx)
                return;
            end
            nodeType = lower(char(string(getField(app, app.Data.nodes(idx), 'type', ''))));
            if strcmp(nodeType, 'dataloader')
                % Normal runs use the global runtime selectors and current
                % raw path. These hidden subset overrides are injected by
                % smoke tests and must not leak into subsequent full runs.
                transientKeys = {'positionIdx','channelIdx','frameRange'};
                for i = 1:numel(transientKeys)
                    if isfield(params, transientKeys{i})
                        params = rmfield(params, transientKeys{i});
                    end
                end
                return;
            end
            if any(strcmp(nodeType, {'roiidentify','roipattern','roimanual','roigrid','roitracked','roiextract'}))
                % These subset selectors belong to the global runtime
                % fields (FOVs/Frames/ROIs) and should not persist as
                % hidden per-node constraints from a previous smoke/test run.
                selectionKeys = {'fovIndex','roiIndex','roiList','frames'};
                for i = 1:numel(selectionKeys)
                    if isfield(params, selectionKeys{i})
                        params = rmfield(params, selectionKeys{i});
                    end
                end
            end
            if any(strcmp(nodeType, {'processor','classifier'}))
                if isfield(params, 'roiList')
                    params = rmfield(params, 'roiList');
                end
                return;
            end
            if ~any(strcmp(nodeType, {'roipattern','roiidentify','roimanual','roigrid','roitracked','roiextract'}))
                return;
            end
            policyKeys = {'keepExisting','skipExisting','errorOnExisting','openFirstOnly','existingPolicy'};
            for i = 1:numel(policyKeys)
                if isfield(params, policyKeys{i})
                    params = rmfield(params, policyKeys{i});
                end
            end
        end

        function target = runtimeExecutionTarget(app)
            target = 'local';
            try
                if isstruct(app.HubFieldHandles) && isfield(app.HubFieldHandles, 'executionTarget') && ...
                        isvalid(app.HubFieldHandles.executionTarget)
                    target = char(string(app.HubFieldHandles.executionTarget.Value));
                elseif isfield(app.RuntimeValues, 'executionTarget') && ~isempty(app.RuntimeValues.executionTarget)
                    target = char(string(app.RuntimeValues.executionTarget));
                end
            catch
                target = 'local';
            end
            if isempty(target)
                target = 'local';
            end
            target = lower(strtrim(char(string(target))));
            target = strrep(target, '-', '_');
            target = strrep(target, ' ', '_');
            if any(strcmp(target, {'local_wsl','wsl','localwsl','local_linux','local/wsl'}))
                target = 'local_wsl';
            elseif strcmp(target, 'hub')
                target = 'hub';
            else
                target = 'local';
            end
        end

        function hub = hubSettingsFromUi(app)
            hub = defaultHubSettingsForUi(app);
            if ~isstruct(app.HubFieldHandles)
                return;
            end
            textKeys = {'baseUrl','userKey','sessionToken','defaultRemoteProjectRoot','defaultLocalProjectRoot'};
            for i = 1:numel(textKeys)
                key = textKeys{i};
                if isfield(app.HubFieldHandles, key) && isvalid(app.HubFieldHandles.(key))
                    hub.(key) = char(string(app.HubFieldHandles.(key).Value));
                end
            end
            if isfield(app.HubFieldHandles, 'fallbackBaseUrls') && isvalid(app.HubFieldHandles.fallbackBaseUrls)
                hub.fallbackBaseUrls = normalizeHubStringList(app, app.HubFieldHandles.fallbackBaseUrls.Value);
            end
            if isfield(app.HubFieldHandles, 'timeout') && isvalid(app.HubFieldHandles.timeout)
                hub.timeout = double(app.HubFieldHandles.timeout.Value);
            end
            hub = addUiPathMappingToHub(app, hub);
        end

        function hub = addUiPathMappingToHub(app, hub)
            remoteRoot = strtrim(char(string(getStructText(app, hub, 'defaultRemoteProjectRoot', ''))));
            localRoot = strtrim(char(string(getStructText(app, hub, 'defaultLocalProjectRoot', ''))));
            if isempty(remoteRoot) || isempty(localRoot)
                return;
            end
            try
                hub = detecdiv_hub_upsert_path_mapping(hub, remoteRoot, localRoot);
            catch
                remoteRoot = regexprep(strrep(remoteRoot, '\', '/'), '[\/]+$', '');
                if ~startsWith(remoteRoot, '/')
                    return;
                end
                if ~isfield(hub, 'pathMappings') || isempty(hub.pathMappings)
                    hub.pathMappings = struct('remoteRoot', {}, 'localRoot', {});
                end
                hub.pathMappings(end+1).remoteRoot = remoteRoot;
                hub.pathMappings(end).localRoot = regexprep(strrep(localRoot, '/', filesep), '[\\\/]+$', '');
            end
        end

        function report = hubPathPreflight(app, hub)
            report = struct('ok', true, 'errors', {{}}, 'warnings', {{}}, ...
                'paths', struct('label', {}, 'localPath', {}, 'remotePath', {}, 'status', {}, 'message', {}));
            checks = collectHubPathChecks(app);
            seen = containers.Map('KeyType', 'char', 'ValueType', 'logical');
            for i = 1:numel(checks)
                localPath = strtrim(char(string(checks(i).path)));
                if isempty(localPath)
                    continue;
                end
                key = lower(strrep(localPath, '/', '\'));
                if isKey(seen, key)
                    continue;
                end
                seen(key) = true;
                [remotePath, ok, message, status] = translateHubPathForServer(app, localPath, hub);
                item = struct('label', checks(i).label, 'localPath', localPath, ...
                    'remotePath', remotePath, 'status', status, 'message', message);
                report.paths(end+1) = item; %#ok<AGROW>
                if ~ok
                    report.ok = false;
                    report.errors{end+1} = sprintf('%s is local-only for Hub: %s. %s', ...
                        checks(i).label, localPath, message); %#ok<AGROW>
                elseif ~isempty(message)
                    report.warnings{end+1} = sprintf('%s: %s', checks(i).label, message); %#ok<AGROW>
                end
            end
        end

        function checks = collectHubPathChecks(app)
            checks = struct('label', {}, 'path', {});
            checks = addHubPathCheck(app, checks, 'Project path', getRuntimeValue(app, 'projectPath'));
            checks = addHubPathCheck(app, checks, 'Raw data path', effectiveRuntimeRawDataPath(app));
            checks = collectClassifierHubPathChecks(app, checks);
            if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                try
                    if isfield(app.CurrentProject.io, 'path') && isfield(app.CurrentProject.io, 'file') && ...
                            ~isempty(app.CurrentProject.io.path) && ~isempty(app.CurrentProject.io.file)
                        checks = addHubPathCheck(app, checks, 'Project data folder', ...
                            fullfile(char(string(app.CurrentProject.io.path)), char(string(app.CurrentProject.io.file))));
                    end
                catch
                end
                checks = collectProjectFovSourceChecks(app, checks, app.CurrentProject);
            end
        end

        function checks = collectClassifierHubPathChecks(app, checks)
            try
                nodes = app.Data.nodes;
            catch
                return;
            end
            for i = 1:numel(nodes)
                try
                    if ~strcmpi(char(string(getField(app, nodes(i), 'type', ''))), 'classifier')
                        continue;
                    end
                    p = getField(app, nodes(i), 'params', struct());
                    if ~isstruct(p) || ~isfield(p, 'modulePath') || isempty(p.modulePath)
                        continue;
                    end
                    modulePath = char(string(p.modulePath));
                    nodeId = char(string(getField(app, nodes(i), 'id', sprintf('classifier_%d', i))));
                    checks = addHubPathCheck(app, checks, sprintf('Classifier %s path', nodeId), modulePath);
                    if isClassifierTrainingIntent(app, p)
                        checks = addHubPathCheck(app, checks, sprintf('Classifier %s trainingdataset', nodeId), ...
                            fullfile(modulePath, 'trainingdataset'));
                    end
                catch
                end
            end
        end

        function tf = isClassifierTrainingIntent(app, p)
            tf = false;
            intentValues = {};
            try
                intentValues{end+1} = getRuntimeValue(app, 'intent');
            catch
            end
            if nargin >= 2 && isstruct(p)
                fields = {'intent','operation','task','runtype','classifierIntent'};
                for k = 1:numel(fields)
                    if isfield(p, fields{k}) && ~isempty(p.(fields{k}))
                        intentValues{end+1} = p.(fields{k}); %#ok<AGROW>
                    end
                end
            end
            for k = 1:numel(intentValues)
                txt = strtrim(char(string(intentValues{k})));
                if any(strcmpi(txt, {'train','training','fit'}))
                    tf = true;
                    return;
                end
            end
        end

        function checks = collectProjectFovSourceChecks(app, checks, shallowObj)
            maxSources = 30;
            sourceCount = 0;
            try
                fovs = shallowObj.fov;
            catch
                return;
            end
            for i = 1:numel(fovs)
                sourceValues = {};
                sourceValues = appendCellTextValues(app, sourceValues, getObjectFieldSafe(app, fovs(i), 'srcpath'));
                sourceValues = appendCellTextValues(app, sourceValues, getObjectFieldSafe(app, fovs(i), 'tiffSource'));
                sourceValues = appendCellTextValues(app, sourceValues, getObjectFieldSafe(app, fovs(i), 'ndtiffPath'));
                sourceValues = appendCellTextValues(app, sourceValues, getObjectFieldSafe(app, fovs(i), 'omeZarrPath'));
                for j = 1:numel(sourceValues)
                    sourceCount = sourceCount + 1;
                    checks = addHubPathCheck(app, checks, sprintf('FOV source %d', i), sourceValues{j}); %#ok<AGROW>
                    if sourceCount >= maxSources
                        return;
                    end
                end
            end
        end

        function value = getObjectFieldSafe(app, obj, fieldName) %#ok<INUSD>
            value = [];
            try
                if isprop(obj, fieldName) || isfield(obj, fieldName)
                    value = obj.(fieldName);
                end
            catch
            end
        end

        function values = appendCellTextValues(app, values, inputValue) %#ok<INUSD>
            if isempty(inputValue)
                return;
            end
            if iscell(inputValue)
                for i = 1:numel(inputValue)
                    values = appendCellTextValues(app, values, inputValue{i}); %#ok<AGROW>
                end
                return;
            end
            if isstring(inputValue)
                for i = 1:numel(inputValue)
                    values{end+1} = char(inputValue(i)); %#ok<AGROW>
                end
                return;
            end
            if ischar(inputValue)
                values{end+1} = inputValue; %#ok<AGROW>
            end
        end

        function checks = addHubPathCheck(app, checks, label, pathValue) %#ok<INUSD>
            pathValue = strtrim(char(string(pathValue)));
            if isempty(pathValue) || strcmpi(pathValue, 'Project source path not resolved')
                return;
            end
            checks(end+1) = struct('label', char(string(label)), 'path', pathValue); %#ok<AGROW>
        end

        function ctx = applyHubPathPreflightToContext(app, ctx, report) %#ok<INUSD>
            if ~isfield(ctx, 'hub') || ~isstruct(ctx.hub)
                ctx.hub = struct();
            end
            for i = 1:numel(report.paths)
                item = report.paths(i);
                if ~strcmp(item.status, 'error') && ~isempty(item.remotePath)
                    label = lower(strrep(item.label, ' ', ''));
                    if strcmp(label, 'rawdatapath')
                        ctx.run.serverRawDataPath = item.remotePath;
                        ctx.io.serverRawDataPath = item.remotePath;
                        ctx.dataLoader.serverPath = item.remotePath;
                    elseif strcmp(label, 'projectpath')
                        ctx.run.serverProjectPath = item.remotePath;
                        ctx.io.serverProjectPath = item.remotePath;
                    elseif strcmp(label, 'projectdatafolder')
                        ctx.run.serverProjectDataFolder = item.remotePath;
                        ctx.io.serverProjectDataFolder = item.remotePath;
                    end
                end
            end
        end

        function [remotePath, ok, message, status] = translateHubPathForServer(app, localPath, hub)
            ok = true;
            message = '';
            status = 'server';
            remotePath = strrep(char(string(localPath)), '\', '/');
            if isempty(strtrim(localPath))
                status = 'empty';
                return;
            end
            if isHubServerPath(app, localPath, hub)
                status = 'server';
                return;
            end
            [mappedPath, mapped] = mapHubPathForServer(app, localPath, hub);
            if mapped
                remotePath = mappedPath;
                status = 'mapped';
                return;
            end
            if isWindowsLocalPath(app, localPath)
                ok = false;
                status = 'error';
                message = hubPathMappingHelpMessage(app, localPath, hub);
                return;
            end
            if ~isAbsoluteServerLikePath(app, localPath)
                ok = false;
                status = 'error';
                message = 'Hub runs need absolute paths visible from the worker, not relative paths.';
                return;
            end
        end

        function tf = isHubServerPath(app, pathValue, hub)
            tf = false;
            pathValue = strrep(char(string(pathValue)), '\', '/');
            if startsWith(pathValue, '/') && ~startsWith(pathValue, '//')
                tf = true;
                return;
            end
            mappings = getHubPathMappings(app, hub);
            for i = 1:numel(mappings)
                remoteRoot = regexprep(strrep(mappings(i).remoteRoot, '\', '/'), '[\/]+$', '');
                if ~isempty(remoteRoot) && hubPathStartsWithRoot(app, pathValue, remoteRoot, false)
                    tf = true;
                    return;
                end
            end
        end

        function [remotePath, mapped] = mapHubPathForServer(app, localPath, hub) %#ok<INUSD>
            remotePath = strrep(char(string(localPath)), '\', '/');
            mapped = false;
            try
                ctx = struct();
                ctx.hub = hub;
                [remotePath, mapped] = detecdiv_paths_map_module_path(localPath, ctx, 'server');
                if mapped
                    remotePath = strrep(char(string(remotePath)), '\', '/');
                end
            catch
                [remotePath, mapped] = hubMappedServerPath(app, localPath, hub);
            end
        end

        function message = hubPathMappingHelpMessage(app, localPath, hub)
            mappings = getHubPathMappings(app, hub);
            active = {};
            for i = 1:numel(mappings)
                try
                    localRoot = char(string(mappings(i).localRoot));
                    remoteRoot = char(string(mappings(i).remoteRoot));
                    if ~isempty(localRoot) && ~isempty(remoteRoot)
                        active{end+1} = sprintf('%s -> %s', localRoot, remoteRoot); %#ok<AGROW>
                    end
                catch
                end
            end
            if isempty(active)
                activeText = 'none';
            else
                activeText = strjoin(active, '; ');
            end
            message = sprintf(['No active Hub path mapping covers this local path: %s.' newline ...
                'Active mapping(s): %s.' newline ...
                'Move/copy the project, classifier, and required data under a mapped local root, or set Local root to the Windows folder that contains them and Remote root to the matching server mount.'], ...
                char(string(localPath)), activeText);
        end

        function tf = isAbsoluteServerLikePath(app, pathValue) %#ok<INUSD>
            pathValue = char(string(pathValue));
            tf = startsWith(pathValue, '/') && ~startsWith(pathValue, '//');
        end

        function tf = isWindowsLocalPath(app, pathValue) %#ok<INUSD>
            pathValue = char(string(pathValue));
            tf = ~isempty(regexp(pathValue, '^[A-Za-z]:[\\/]*', 'once')) || ...
                startsWith(pathValue, '\\') || startsWith(pathValue, '//');
        end

        function [remotePath, mapped] = hubMappedServerPath(app, localPath, hub)
            mapped = false;
            remotePath = strrep(char(string(localPath)), '\', '/');
            localComparable = strrep(char(string(localPath)), '/', '\');
            mappings = getHubPathMappings(app, hub);
            bestLen = -1;
            bestRemote = '';
            bestSuffix = '';
            for i = 1:numel(mappings)
                localRoot = regexprep(strrep(char(string(mappings(i).localRoot)), '/', '\'), '[\\\/]+$', '');
                remoteRoot = regexprep(strrep(char(string(mappings(i).remoteRoot)), '\', '/'), '[\/]+$', '');
                if isempty(localRoot) || isempty(remoteRoot)
                    continue;
                end
                if hubPathStartsWithRoot(app, localComparable, localRoot, true) && numel(localRoot) > bestLen
                    suffix = localComparable(numel(localRoot)+1:end);
                    suffix = strrep(suffix, '\', '/');
                    bestLen = numel(localRoot);
                    bestRemote = remoteRoot;
                    bestSuffix = suffix;
                end
            end
            if bestLen >= 0
                remotePath = [bestRemote bestSuffix];
                mapped = true;
            end
        end

        function tf = hubPathStartsWithRoot(app, pathValue, rootValue, ignoreCase) %#ok<INUSD>
            pathValue = char(string(pathValue));
            rootValue = char(string(rootValue));
            if ignoreCase
                pathCmp = lower(pathValue);
                rootCmp = lower(rootValue);
            else
                pathCmp = pathValue;
                rootCmp = rootValue;
            end
            tf = startsWith(pathCmp, rootCmp);
            if ~tf
                return;
            end
            if numel(pathCmp) == numel(rootCmp)
                return;
            end
            if endsWith(rootCmp, ':')
                return;
            end
            nextChar = pathCmp(numel(rootCmp)+1);
            tf = any(nextChar == ['\' '/']);
        end

        function mappings = getHubPathMappings(app, hub) %#ok<INUSD>
            mappings = struct('remoteRoot', {}, 'localRoot', {});
            try
                ctx = struct();
                if nargin >= 2 && isstruct(hub)
                    ctx.hub = hub;
                else
                    ctx.hub = struct();
                end
                mappings = detecdiv_paths_module_mappings(ctx);
            catch
                try
                    if isfield(hub, 'pathMappings') && ~isempty(hub.pathMappings)
                        mappings = hub.pathMappings;
                    end
                catch
                    mappings = struct('remoteRoot', {}, 'localRoot', {});
                end
            end
        end

        function password = hubPasswordFromUi(app)
            password = '';
            flushHubPasswordControl(app);
            try
                if isstruct(app.HubFieldHandles) && isfield(app.HubFieldHandles, 'password') && ...
                        isvalid(app.HubFieldHandles.password)
                    if isprop(app.HubFieldHandles.password, 'Data')
                        password = char(string(app.HubFieldHandles.password.Data));
                    else
                        password = char(string(app.HubFieldHandles.password.Value));
                    end
                end
            catch
                password = '';
            end
            if isempty(password)
                try
                    if isfield(app.RuntimeValues, 'hubPassword')
                        password = char(string(app.RuntimeValues.hubPassword));
                    end
                catch
                end
            end
        end

        function flushHubPasswordControl(app)
            try
                if isstruct(app.HubFieldHandles) && isfield(app.HubFieldHandles, 'password') && ...
                        isvalid(app.HubFieldHandles.password) && isprop(app.HubFieldHandles.password, 'Data')
                    app.HubFieldHandles.password.Data = struct('command', 'flush', 'nonce', char(string(datetime('now'))));
                    drawnow;
                    pause(0.05);
                    drawnow;
                end
            catch
            end
        end

        function setHubPasswordValue(app, value)
            try
                if isstruct(app.HubFieldHandles) && isfield(app.HubFieldHandles, 'password') && ...
                        isvalid(app.HubFieldHandles.password)
                    if isprop(app.HubFieldHandles.password, 'Data')
                        app.HubFieldHandles.password.Data = char(string(value));
                    else
                        app.HubFieldHandles.password.Value = char(string(value));
                    end
                    app.RuntimeValues.hubPassword = char(string(value));
                end
            catch
            end
        end

        function persistHubSettingsFromUi(app)
            try
                if ~isstruct(app.HubFieldHandles) || isempty(fieldnames(app.HubFieldHandles))
                    return;
                end
                hub = hubSettingsFromUi(app);
                detecdiv_hub_settings_set(hub);
            catch
            end
        end

        function connectHubButtonPushed(app)
            d = openRuntimeProgress(app, 'DetecDiv Hub', 'Connecting to hub...');
            try
                hub = hubSettingsFromUi(app);
                if ~isfield(hub, 'baseUrl') || isempty(strtrim(char(string(hub.baseUrl))))
                    error('pipeline2:HubMissingUrl', 'Hub URL is required.');
                end
                hub = ensureHubSessionFromUi(app, hub);
                hub = requireHubSessionForStatus(app, hub);
                updateRuntimeProgress(app, d, 'Checking hub status...');
                [hubStatus, info] = queryHubStatus(app, hub);
                detecdiv_hub_settings_set(hub);
                applyHubSettingsToUi(app, hub);
                resumeHubRunStatusMonitorAfterReconnect(app, hub);
                setHubPasswordValue(app, '');
                closeRuntimeProgress(app, d);
                summaryText = formatHubStatusSummary(app, hubStatus);
                setRuntimeStatus(app, ['Hub connected: ' summaryText]);
                appendRunReport(app, 'Hub status: OK', struct('summary', hubStatus.summary));
                try
                    uialert(app.UIFigure, ['Connected to DetecDiv Hub.' newline summaryText newline char(string(info.url))], ...
                        'Hub connection', 'Icon', 'success');
                catch
                end
            catch ME
                closeRuntimeProgress(app, d);
                try
                    uialert(app.UIFigure, ME.message, 'Hub connection failed', 'Icon', 'error');
                catch
                end
            end
            refreshValidationReport(app);
        end

        function resumeHubRunStatusMonitorAfterReconnect(app, hub)
            jobId = currentHubRunJobId(app);
            if isempty(jobId)
                return;
            end

            hubStatus = '';
            runStatus = '';
            try
                if ~isempty(app.CurrentRun) && isa(app.CurrentRun, 'pipelineRun')
                    runStatus = char(string(app.CurrentRun.status));
                    if isstruct(app.CurrentRun.ctx) && isfield(app.CurrentRun.ctx, 'hub') && ...
                            isstruct(app.CurrentRun.ctx.hub) && isfield(app.CurrentRun.ctx.hub, 'status')
                        hubStatus = char(string(app.CurrentRun.ctx.hub.status));
                    end
                end
            catch
                hubStatus = '';
            end
            if isempty(hubStatus) && startsWith(lower(runStatus), 'hub_')
                hubStatus = char(extractAfter(string(runStatus), 4));
            end

            hasActiveStatus = any(strcmpi(hubStatus, {'queued','running','cancelling'}));
            monitorWasActive = strcmpi(char(string(app.ActiveRunMode)), 'hub') || ...
                ~isempty(app.HubRunMonitorToken) || hasActiveStatus;
            if ~monitorWasActive
                return;
            end

            oldToken = app.HubRunMonitorToken;
            app.HubRunMonitorToken = '';
            if ~isempty(oldToken)
                try
                    detecdiv_hub_broker('unregister', oldToken);
                catch
                end
            end

            app.HubRunMonitorJobId = jobId;
            try
                app.ActiveRunMode = 'hub';
                applyHubRunUiLock(app, true);
                startHubRunStatusWorker(app, jobId, false, Inf, hub);
                appendMonitorConsole(app, ...
                    '[monitor] Hub connection restored; status polling resumed.');
                setRuntimeStatus(app, ['Hub reconnected; resuming monitor for job ' jobId '.']);
            catch ME
                app.HubRunMonitorToken = '';
                message = ['Hub connected, but the run monitor could not restart: ' ME.message];
                appendMonitorConsole(app, ['[monitor] ' message]);
                setRuntimeStatus(app, message);
            end
        end

        function [status, info] = queryHubStatus(app, hub)
            [health, info] = detecdiv_hub_request('GET', '/health', [], hub);
            auth = struct();
            targets = struct([]);
            jobs = struct([]);
            targetError = '';
            jobError = '';
            try
                auth = detecdiv_hub_request('GET', '/auth/session', [], hub);
            catch ME
                targetError = ['Authentication unavailable: ' ME.message];
            end
            try
                if isempty(targetError)
                    targets = detecdiv_hub_request('GET', '/execution-targets', [], hub);
                end
            catch ME
                targetError = ME.message;
            end
            try
                jobs = detecdiv_hub_request('GET', '/jobs', [], hub);
            catch ME
                jobError = ME.message;
            end
            worker = summarizeHubWorkers(app, targets);
            queue = summarizeHubJobs(app, jobs);
            summary = struct( ...
                'database', char(string(getField(app, health, 'database_status', 'unknown'))), ...
                'hostname', char(string(getField(app, health, 'hostname', ''))), ...
                'auth_mode', char(string(getField(app, auth, 'auth_mode', 'unknown'))), ...
                'worker_available', worker.availableWorkers, ...
                'worker_active', worker.activeWorkers, ...
                'worker_busy', worker.busyWorkers, ...
                'worker_stale', worker.staleWorkers, ...
                'worker_error', worker.errorWorkers, ...
                'queued_jobs', queue.queued, ...
                'queued_pipeline_runs', queue.queuedPipelineRuns, ...
                'running_jobs', queue.running);
            if ~isempty(targetError)
                summary.execution_targets_error = targetError;
            end
            if ~isempty(jobError)
                summary.jobs_error = jobError;
            end
            status = struct('health', health, 'auth', auth, 'worker', worker, 'queue', queue, ...
                'summary', summary, 'executionTargetsError', targetError, 'jobsError', jobError);
        end

        function worker = summarizeHubWorkers(app, targets)
            worker = struct('targetCount', 0, 'activeWorkers', 0, 'registeredWorkers', 0, ...
                'availableWorkers', 0, 'busyWorkers', 0, 'onlineWorkers', 0, ...
                'staleWorkers', 0, 'errorWorkers', 0, 'capacity', 0, 'details', {{}});
            if isempty(targets)
                return;
            end
            useMatlabOnly = false;
            for i = 1:numel(targets)
                target = localListItem(app, targets, i);
                if isstruct(target) && isfield(target, 'supports_matlab') && logical(target.supports_matlab)
                    useMatlabOnly = true;
                    break;
                end
            end
            for i = 1:numel(targets)
                target = localListItem(app, targets, i);
                if ~isstruct(target)
                    continue;
                end
                if useMatlabOnly && isfield(target, 'supports_matlab') && ~logical(target.supports_matlab)
                    continue;
                end
                metadata = getField(app, target, 'metadata_json', struct());
                wh = getField(app, metadata, 'worker_health_summary', struct());
                if isempty(fieldnamesOrEmpty(app, wh))
                    wh = getField(app, metadata, 'worker_health', struct());
                end
                active = numericField(app, wh, 'worker_count', 0);
                registered = numericField(app, wh, 'registered_workers', active);
                busy = numericField(app, wh, 'busy_workers', 0);
                online = numericField(app, wh, 'online_workers', active);
                stale = numericField(app, wh, 'stale_workers', max(0, registered - active));
                errors = numericField(app, wh, 'error_workers', 0);
                capacity = numericField(app, wh, 'max_concurrent_jobs', 0);
                if capacity <= 0
                    capacity = numericField(app, metadata, 'max_concurrent_jobs', active);
                end
                if capacity <= 0
                    capacity = active;
                end
                available = max(0, capacity - busy);
                worker.targetCount = worker.targetCount + 1;
                worker.activeWorkers = worker.activeWorkers + active;
                worker.registeredWorkers = worker.registeredWorkers + registered;
                worker.availableWorkers = worker.availableWorkers + available;
                worker.busyWorkers = worker.busyWorkers + busy;
                worker.onlineWorkers = worker.onlineWorkers + online;
                worker.staleWorkers = worker.staleWorkers + stale;
                worker.errorWorkers = worker.errorWorkers + errors;
                worker.capacity = worker.capacity + capacity;
                worker.details{end+1} = sprintf('%s: %d/%d available, %d busy, %d stale', ...
                    char(string(getField(app, target, 'display_name', getField(app, target, 'target_key', 'target')))), ...
                    available, capacity, busy, stale); %#ok<AGROW>
            end
        end

        function queue = summarizeHubJobs(app, jobs)
            queue = struct('total', 0, 'queued', 0, 'running', 0, 'cancelling', 0, ...
                'queuedPipelineRuns', 0, 'runningPipelineRuns', 0);
            if isempty(jobs)
                return;
            end
            queue.total = numel(jobs);
            for i = 1:numel(jobs)
                job = localListItem(app, jobs, i);
                if ~isstruct(job)
                    continue;
                end
                st = lower(strtrim(char(string(getField(app, job, 'status', '')))));
                params = getField(app, job, 'params_json', struct());
                kind = lower(strtrim(char(string(getField(app, params, 'job_kind', '')))));
                switch st
                    case 'queued'
                        queue.queued = queue.queued + 1;
                        if strcmp(kind, 'pipeline_run')
                            queue.queuedPipelineRuns = queue.queuedPipelineRuns + 1;
                        end
                    case 'running'
                        queue.running = queue.running + 1;
                        if strcmp(kind, 'pipeline_run')
                            queue.runningPipelineRuns = queue.runningPipelineRuns + 1;
                        end
                    case 'cancelling'
                        queue.cancelling = queue.cancelling + 1;
                end
            end
        end

        function text = formatHubStatusSummary(app, status)
            worker = status.worker;
            queue = status.queue;
            db = char(string(getField(app, status.summary, 'database', 'unknown')));
            authMode = char(string(getField(app, status.summary, 'auth_mode', 'unknown')));
            if isempty(status.executionTargetsError)
                workerText = sprintf('workers %d/%d available (%d active, %d busy, %d stale, %d error)', ...
                    worker.availableWorkers, worker.capacity, worker.activeWorkers, worker.busyWorkers, ...
                    worker.staleWorkers, worker.errorWorkers);
            else
                workerText = ['workers unavailable: ' compactStatusMessage(app, status.executionTargetsError)];
            end
            text = sprintf('DB %s, auth %s, %s, queue %d queued (%d pipeline), %d running', ...
                db, authMode, workerText, queue.queued, queue.queuedPipelineRuns, queue.running);
            if ~isempty(status.jobsError)
                text = [text ', jobs unavailable: ' compactStatusMessage(app, status.jobsError)];
            end
        end

        function msg = compactStatusMessage(app, msg) %#ok<INUSD>
            msg = strtrim(char(string(msg)));
            msg = regexprep(msg, '\s+', ' ');
            maxLen = 160;
            if strlength(string(msg)) > maxLen
                msg = [char(extractBefore(string(msg), maxLen)) '...'];
            end
        end

        function value = localListItem(app, values, idx) %#ok<INUSD>
            if iscell(values)
                value = values{idx};
            else
                value = values(idx);
            end
        end

        function names = fieldnamesOrEmpty(app, value) %#ok<INUSD>
            if isstruct(value)
                names = fieldnames(value);
            else
                names = {};
            end
        end

        function value = numericField(app, S, key, defaultValue)
            value = defaultValue;
            try
                if isstruct(S) && isfield(S, key) && ~isempty(S.(key))
                    value = double(S.(key));
                end
            catch
                value = defaultValue;
            end
        end

        function hub = requireHubSessionForStatus(app, hub)
            if isfield(hub, 'sessionToken') && ~isempty(strtrim(char(string(hub.sessionToken))))
                try
                    detecdiv_hub_request('GET', '/auth/session', [], hub);
                    return;
                catch ME
                    error('pipeline2:HubSessionInvalid', ...
                        ['Hub session token is not valid for status endpoints: ' ME.message ...
                         newline 'Reconnect with your password or paste a valid session token.']);
                end
            end
            error('pipeline2:HubAuthenticationRequired', ...
                ['Hub is reachable, but worker status requires an authenticated session.' newline ...
                 'Enter your password and click Connect, or paste a valid session token.']);
        end

        function hub = ensureHubSessionFromUi(app, hub)
            password = hubPasswordFromUi(app);
            hasToken = isfield(hub, 'sessionToken') && ~isempty(strtrim(char(string(hub.sessionToken))));
            userKey = '';
            if isfield(hub, 'userKey')
                userKey = strtrim(char(string(hub.userKey)));
            end
            if hasToken
                tokenUserKey = hubSessionUserKey(app, hub);
                if isempty(userKey) || strcmpi(tokenUserKey, userKey)
                    return;
                end
                hub.sessionToken = '';
                hasToken = false;
                if isfield(app.HubFieldHandles, 'sessionToken') && isvalid(app.HubFieldHandles.sessionToken)
                    app.HubFieldHandles.sessionToken.Value = '';
                end
                setRuntimeStatus(app, sprintf('Hub session token belonged to %s; reconnect as %s.', tokenUserKey, userKey));
            end
            if isempty(strtrim(password)) && ~hasToken
                password = promptHubPassword(app, hub);
            end
            if isempty(strtrim(password))
                return;
            end
            if isempty(userKey)
                error('pipeline2:HubMissingUserKey', 'Hub user key is required for password login.');
            end
            [~, hub] = detecdiv_hub_login(userKey, password, hub);
            if isfield(app.HubFieldHandles, 'sessionToken') && isvalid(app.HubFieldHandles.sessionToken) && ...
                    isfield(hub, 'sessionToken')
                app.HubFieldHandles.sessionToken.Value = char(string(hub.sessionToken));
            end
            setHubPasswordValue(app, '');
        end

        function userKey = hubSessionUserKey(app, hub) %#ok<INUSD>
            userKey = '';
            session = detecdiv_hub_request('GET', '/auth/session', [], hub);
            try
                if isstruct(session) && isfield(session, 'user') && isstruct(session.user) && ...
                        isfield(session.user, 'user_key') && ~isempty(session.user.user_key)
                    userKey = char(string(session.user.user_key));
                end
            catch
                userKey = '';
            end
        end

        function password = promptHubPassword(app, hub)
            password = '';
            userKey = '';
            try
                if isfield(hub, 'userKey')
                    userKey = strtrim(char(string(hub.userKey)));
                end
            catch
                userKey = '';
            end
            try
                import javax.swing.*
                panel = javaObjectEDT('javax.swing.JPanel');
                panel.setLayout(javaObjectEDT('java.awt.GridLayout', 0, 1));
                label = javaObjectEDT('javax.swing.JLabel', ['Password for Hub user ' userKey ':']);
                field = javaObjectEDT('javax.swing.JPasswordField', 24);
                panel.add(label);
                panel.add(field);
                option = JOptionPane.showConfirmDialog([], panel, 'DetecDiv Hub login', ...
                    JOptionPane.OK_CANCEL_OPTION, JOptionPane.PLAIN_MESSAGE);
                if option == JOptionPane.OK_OPTION
                    password = char(field.getPassword())';
                end
            catch
                try
                    answer = inputdlg({['Password for Hub user ' userKey ':']}, 'DetecDiv Hub login', 1, {''});
                    if ~isempty(answer)
                        password = char(string(answer{1}));
                    end
                catch
                    password = '';
                end
            end
        end

        function applyHubSettingsToUi(app, hub)
            if ~isstruct(hub) || ~isstruct(app.HubFieldHandles)
                return;
            end
            textKeys = {'baseUrl','userKey','sessionToken','defaultRemoteProjectRoot','defaultLocalProjectRoot'};
            for i = 1:numel(textKeys)
                key = textKeys{i};
                if isfield(hub, key) && isfield(app.HubFieldHandles, key) && isvalid(app.HubFieldHandles.(key))
                    app.HubFieldHandles.(key).Value = char(string(hub.(key)));
                end
            end
            if isfield(hub, 'fallbackBaseUrls') && isfield(app.HubFieldHandles, 'fallbackBaseUrls') && isvalid(app.HubFieldHandles.fallbackBaseUrls)
                app.HubFieldHandles.fallbackBaseUrls.Value = strjoin(normalizeHubStringList(app, hub.fallbackBaseUrls), ', ');
            end
            if isfield(hub, 'timeout') && isfield(app.HubFieldHandles, 'timeout') && isvalid(app.HubFieldHandles.timeout)
                app.HubFieldHandles.timeout.Value = double(hub.timeout);
            end
        end

        function nodeId = runtimeKeyToNodeId(app, key)
            nodeId = '';
            prefix = 'node_';
            key = char(string(key));
            if startsWith(key, prefix)
                candidate = key(numel(prefix)+1:end);
                ids = {};
                if ~isempty(app.Data.nodes)
                    ids = cellstr(string({app.Data.nodes.id}));
                end
                validIds = cellfun(@(s)matlab.lang.makeValidName(s), ids, 'UniformOutput', false);
                idx = find(strcmp(validIds, candidate), 1);
                if ~isempty(idx)
                    nodeId = ids{idx};
                end
            end
        end

        function ids = selectedRunNodeIds(app)
            ids = {};
            data = app.UISelectedModuleTable.Data;
            if isempty(data)
                if ~isempty(app.Data.nodes)
                    ids = {};
                    for ii = 1:numel(app.Data.nodes)
                        ids{end+1} = char(string(app.Data.nodes(ii).id)); %#ok<AGROW>
                    end
                end
                return;
            end
            for i = 1:size(data, 1)
                include = true;
                try
                    include = logical(data{i,1});
                catch
                end
                if include
                    nodeId = char(string(data{i,2}));
                    ids{end+1} = nodeId; %#ok<AGROW>
                end
            end
        end

        function ids = smokeRunSelectedNodeIds(app)
            ids = selectedRunNodeIds(app);
        end

        function tf = runtimeRunSelectionAllowsNode(app, nodeId)
            tf = true;
        end

        function enableRawPrepNodesInRunTable(app)
            data = app.UISelectedModuleTable.Data;
            if isempty(data)
                return;
            end
            for i = 1:size(data, 1)
                nodeId = char(string(data{i,2}));
                idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
                if isempty(idx)
                    continue;
                end
                if isRawPrepNode(app, app.Data.nodes(idx))
                    data{i,1} = true;
                end
            end
            app.UISelectedModuleTable.Data = data;
            refreshSelectedModuleTableStyles(app);
        end

        function disableRawPrepNodesInRunTable(app)
            data = app.UISelectedModuleTable.Data;
            if isempty(data)
                return;
            end
            for i = 1:size(data, 1)
                nodeId = char(string(data{i,2}));
                idx = find(strcmp({app.Data.nodes.id}, nodeId), 1);
                if isempty(idx)
                    continue;
                end
                if isRawPrepNode(app, app.Data.nodes(idx))
                    data{i,1} = false;
                end
            end
            app.UISelectedModuleTable.Data = data;
            refreshSelectedModuleTableStyles(app);
        end

        function policy = resumeModeToRunPolicy(app, value) %#ok<INUSD>
            if strcmpi(char(string(value)), 'Restart from scratch')
                policy = 'restart';
            else
                policy = 'resume';
            end
        end

        function idx = parseIndexSelection(app, txt) %#ok<INUSD>
            idx = [];
            txt = strtrim(char(string(txt)));
            if isempty(txt) || strcmpi(txt, 'all') || startsWith(lower(txt), 'all ')
                return;
            end
            try
                idx = str2num(txt); %#ok<ST2NM>
                idx = idx(:)';
                idx = idx(isfinite(idx) & idx > 0);
                idx = unique(round(idx), 'stable');
            catch
                idx = [];
            end
        end

        function idx = parseRuntimeFovSelection(app, txt)
            % Numeric selectors are always 1-based vector indices. Project
            % FOV IDs are accepted only when written explicitly, avoiding
            % guesses between an index and an acquisition position number.
            idx = parseIndexSelection(app, txt);
            if ~runtimeStartsFromExistingProject(app) || ...
                    isempty(app.CurrentProject) || ...
                    ~isa(app.CurrentProject, 'shallow')
                return;
            end
            textValue = strtrim(char(string(txt)));
            if isempty(textValue) || strcmpi(textValue, 'all') || ...
                    startsWith(lower(textValue), 'all ')
                idx = [];
                return;
            end
            fovs = app.CurrentProject.fov;
            if isempty(fovs)
                return;
            end
            if ~isempty(idx) && isempty(regexp(textValue, '[A-Za-z]', 'once'))
                return;
            end

            % Parse mixed lists token by token, allowing forms such as
            % "2,Pos11_3". Ranges remain numeric index selections.
            parts = regexp(strrep(textValue, ';', ','), '[,\s]+', 'split');
            selected = [];
            for partIndex = 1:numel(parts)
                token = strtrim(parts{partIndex});
                if isempty(token)
                    continue;
                end
                tokenValues = str2num(token); %#ok<ST2NM>
                if ~isempty(tokenValues)
                    selected = [selected tokenValues(:)']; %#ok<AGROW>
                    continue;
                end
                match = findProjectFovPositionId(app, fovs, token);
                if ~isempty(match)
                    selected(end+1) = match; %#ok<AGROW>
                else
                    % Preserve an invalid selection so validation blocks the
                    % run instead of treating an unknown ID as "all FOVs".
                    selected(end+1) = numel(fovs) + 1; %#ok<AGROW>
                end
            end
            if ~isempty(selected)
                idx = unique(round(selected), 'stable');
            end
        end

        function match = findProjectFovPositionId(app, fovs, selector) %#ok<INUSD>
            match = [];
            selector = strtrim(char(string(selector)));
            if isempty(selector)
                return;
            end
            names = cell(1, numel(fovs));
            for fovIndex = 1:numel(fovs)
                try
                    names{fovIndex} = char(string(fovs(fovIndex).id));
                catch
                    names{fovIndex} = '';
                end
            end
            match = find(strcmpi(names, selector), 1);
        end

        function indices = runtimeFovSelectionIndices(app, selected)
            if runtimeStartsFromClassifier(app)
                indices = [];
                return;
            end
            indices = selected;
            if isempty(selected)
                count = 0;
                if runtimeStartsFromExistingProject(app) && ...
                        ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                    count = numel(app.CurrentProject.fov);
                elseif isfield(app.RuntimeParseInfo, 'fovCount')
                    count = max(0, round(double(app.RuntimeParseInfo.fovCount)));
                end
                if count > 0
                    indices = 1:count;
                end
            end
            indices = unique(round(double(indices(:)')), 'stable');
        end

        function ids = runtimeFovSelectionIds(app, selected)
            ids = {};
            if runtimeStartsFromClassifier(app)
                return;
            end
            if runtimeStartsFromExistingProject(app) && ...
                    ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                fovs = app.CurrentProject.fov;
                indices = runtimeFovSelectionIndices(app, selected);
                indices = indices(indices >= 1 & indices <= numel(fovs));
                for i = 1:numel(indices)
                    try
                        ids{end+1} = char(string(fovs(indices(i)).id)); %#ok<AGROW>
                    catch
                        ids{end+1} = ''; %#ok<AGROW>
                    end
                end
            elseif isfield(app.RuntimeParseInfo, 'fovNames')
                names = app.RuntimeParseInfo.fovNames;
                indices = runtimeFovSelectionIndices(app, selected);
                indices = indices(indices >= 1 & indices <= numel(names));
                ids = cellstr(string(names(indices)));
            end
        end

        function out = parseLooseSelection(app, txt) %#ok<INUSD>
            out = [];
            txt = strtrim(char(string(txt)));
            if isempty(txt) || strcmpi(txt, 'all') || startsWith(lower(txt), 'all ')
                return;
            end
            nums = str2num(txt); %#ok<ST2NM>
            if ~isempty(nums)
                out = nums(:)';
            else
                out = cellstr(string(strsplit(txt, ',')));
            end
        end

        function ref = buildPipelineRef(app)
            ref = struct('id', currentPipelineName(app), 'path', canonicalPipelineTemplatePath(app, app.CurrentPipelinePath), 'version', '');
            if ~isempty(app.CurrentPipeline) && isa(app.CurrentPipeline, 'pipeline')
                ref.id = currentPipelineName(app);
                ref.path = canonicalPipelineTemplatePath(app, app.CurrentPipeline.path);
                ref.version = app.CurrentPipeline.version;
            end
        end

        function pathOut = canonicalPipelineTemplatePath(app, pathIn)
            pathOut = char(string(pathIn));
            if isempty(pathOut) || ~runtimeStartsFromClassifier(app) || ~isHubBundlePipelinePath(app, pathOut)
                return;
            end
            classiPath = classifierScopedRunRoot(app, false);
            if isempty(classiPath)
                return;
            end
            templateId = currentPipelineName(app);
            if isempty(templateId) || strcmpi(templateId, 'pipeline') || strcmpi(templateId, guiAppName(app))
                try
                    if ~isempty(app.CurrentRun) && isa(app.CurrentRun, 'pipelineRun')
                        if ~isempty(app.CurrentRun.templateId)
                            templateId = char(string(app.CurrentRun.templateId));
                        elseif isstruct(app.CurrentRun.pipelineRef) && isfield(app.CurrentRun.pipelineRef, 'id') && ~isempty(app.CurrentRun.pipelineRef.id)
                            templateId = char(string(app.CurrentRun.pipelineRef.id));
                        end
                    end
                catch
                end
            end
            candidate = fullfile(classiPath, 'pipeline_templates', templateId);
            if exist(fullfile(candidate, 'pipeline.json'), 'file') == 2
                pathOut = candidate;
            end
        end

        function tf = isHubBundlePipelinePath(app, pathValue) %#ok<INUSD>
            txt = lower(strrep(char(string(pathValue)), '\', '/'));
            tf = contains(txt, '/pipeline_runs/') && contains(txt, '/hub_pipeline_bundle/pipeline');
        end

        function ref = buildTargetRef(app)
            ref = struct('type', 'shallow', 'projectPath', getRuntimeValue(app, 'projectPath'), ...
                'projectName', '', 'fovIds', parseRuntimeFovSelection(app, getRuntimeValue(app, 'fovs')), ...
                'roiIds', {{}}, 'classiPath', '', 'notes', '');
            ref.roiIds = parseLooseSelection(app, getRuntimeValue(app, 'rois'));
            if runtimeStartsFromClassifier(app) && ~isempty(app.ExplicitRuntimeRoiList)
                classiPath = classifierScopedRunRoot(app, false);
                if ~isempty(classiPath)
                    ref.type = 'classi';
                    ref.projectPath = '';
                    ref.projectName = '';
                    ref.classiPath = classiPath;
                    ref.notes = 'Classifier-scoped run using ROIs from the selected source classifier.';
                end
            elseif ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                [pth, file] = app.CurrentProject.getPath;
                ref.projectPath = fullfile(pth, file);
                ref.projectName = file;
            elseif ~isempty(app.ExplicitRuntimeRoiList)
                classiPath = classifierScopedRunRoot(app, false);
                if ~isempty(classiPath)
                    ref.type = 'classi';
                    ref.projectPath = '';
                    ref.projectName = '';
                    ref.classiPath = classiPath;
                    ref.notes = 'Classifier-scoped run using explicit classifier.roi runtime handles.';
                end
            end
        end

        function ok = ensurePipelineSavedForRun(app)
            ok = true;
            if isempty(app.CurrentPipelinePath)
                choice = uiconfirm(app.UIFigure, ...
                    'This run needs a saved pipeline template reference. Save the pipeline now?', ...
                    'Save pipeline before run', 'Options', {'Save as...','Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 2);
                if strcmp(choice, 'Cancel')
                    ok = false;
                    return;
                end
                ok = savePipelineInteractive(app, true);
            else
                if app.CurrentPipelineIsRunSnapshot || isRunSnapshotPipelinePath(app, app.CurrentPipelinePath)
                    if app.IsPipelineDirty
                        choice = uiconfirm(app.UIFigure, ...
                            ['This window was opened from a run snapshot, not from the editable pipeline template.' newline ...
                             'Save these pipeline edits as a new template before launching?'], ...
                            'Run snapshot pipeline', 'Options', {'Save as...','Run without template save','Cancel'}, ...
                            'DefaultOption', 2, 'CancelOption', 3);
                        switch choice
                            case 'Save as...'
                                ok = savePipelineInteractive(app, true);
                            case 'Run without template save'
                                ok = true;
                            otherwise
                                ok = false;
                        end
                    end
                    return;
                end
                if app.IsPipelineDirty
                    ok = savePipelineInteractive(app, false);
                end
            end
        end

        function ok = ensureCurrentProjectForRun(app)
            ok = false;
            if runtimeStartsFromClassifier(app)
                ok = ~isempty(app.ExplicitRuntimeRoiList) && ~isempty(classifierScopedRunRoot(app, false));
                return;
            end
            if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                ok = true;
                return;
            end
            projectPath = getRuntimeValue(app, 'projectPath');
            if ~isempty(projectPath)
                bindProjectFromPath(app, projectPath, false);
                if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                    ok = true;
                    return;
                end
            end
            if ~isempty(app.ExplicitRuntimeRoiList) && ~isempty(classifierScopedRunRoot(app, false))
                ok = true;
                return;
            end
            choice = uiconfirm(app.UIFigure, ...
                'A persistent run requires a shallow project. Create or load a project now?', ...
                'Project required', 'Options', {'New project...','Browse existing...','Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3);
            switch choice
                case 'New project...'
                    createNewProjectFromDialog(app);
                case 'Browse existing...'
                    chooseExistingProject(app);
                otherwise
                    return;
            end
            ok = ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow');
        end

        function runObj = createOrUpdateCurrentRun(app, ctx, status, forceNew, requestedRunId)
            if nargin < 4 || isempty(forceNew)
                forceNew = false;
            end
            if nargin < 5
                requestedRunId = '';
            end
            if isempty(strtrim(char(string(requestedRunId))))
                requestedRunId = runtimeRunIdFromUi(app);
            end
            if ~isempty(app.CurrentRun) && isa(app.CurrentRun, 'pipelineRun') && ~isempty(strtrim(char(string(requestedRunId))))
                try
                    forceNew = forceNew || ~strcmp(char(string(app.CurrentRun.runId)), char(string(requestedRunId)));
                catch
                end
            end
            ctxForStorage = stripTransientRunContext(app, ctx);
            createNewRun = logical(forceNew) || app.CurrentRunIsSeed || isempty(app.CurrentRun) || ~isa(app.CurrentRun, 'pipelineRun');
            if createNewRun
                ref = buildPipelineRef(app);
                target = buildTargetRef(app);
                % Publish once after saving, when run.json is available.
                args = {'ctx', ctxForStorage, 'status', status, 'pipelineRef', ref, 'targetRef', target, 'notifyWorkspace', false};
                if ~isempty(strtrim(char(string(requestedRunId))))
                    args = [{'runId', char(string(requestedRunId))} args]; %#ok<AGROW>
                end
                if ~runtimeStartsFromClassifier(app) && ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                    runObj = pipelineRunNew(app.CurrentProject, ref.id, ref.path, args{:});
                else
                    runObj = createClassifierScopedPipelineRun(app, ref, target, ctxForStorage, status, requestedRunId);
                end
                if app.CurrentRunIsSeed && ~isempty(app.CurrentRunSourceId)
                    logRunEvent(app, runObj, ['New run created from existing run ' app.CurrentRunSourceId '.'], 'pipeline2');
                end
                app.CurrentRun = runObj;
                [runPath, ~] = runObj.getPath;
                app.CurrentRunPath = runPath;
                app.CurrentRunIsSeed = false;
                app.CurrentRunSourceId = '';
            else
                runObj = app.CurrentRun;
                runObj.ctx = attachRunArtifactPathsToContext(app, ctxForStorage, runObj);
                runObj.status = status;
                runObj.pipelineRef = buildPipelineRef(app);
                runObj.targetRef = buildTargetRef(app);
                runObj.templateId = runObj.pipelineRef.id;
                runObj.templatePath = runObj.pipelineRef.path;
            end
        end

        function runObj = createClassifierScopedPipelineRun(app, ref, target, ctx, status, requestedRunId)
            classiPath = classifierScopedRunRoot(app, true);
            if isempty(classiPath)
                error('pipeline2:ClassifierRunNoRoot', ...
                    'Cannot create a classifier-scoped run because the classifier path is unavailable.');
            end
            runId = char(string(requestedRunId));
            if isempty(strtrim(runId))
                runId = suggestNextClassifierRunId(app, classiPath, ref.id);
            end
            runObj = pipelineRun('', runId, 1);
            runRoot = fullfile(classiPath, 'pipeline_runs', runId);
            if exist(runRoot, 'dir') ~= 7
                mkdir(runRoot);
            end
            runObj.path = runRoot;
            runObj.pipelineRef = ref;
            runObj.targetRef = target;
            runObj.templateId = ref.id;
            runObj.templatePath = ref.path;
            runObj.projectPath = '';
            runObj.projectName = '';
            runObj.description = 'Classifier-scoped pipeline run.';
            runObj.status = status;
            runObj.ctx = attachRunArtifactPathsToContext(app, ctx, runObj);
            runObj.ctx.targetRef = target;
            runObj.ctx.pipelineRef = ref;
        end

        function root = classifierScopedRunRoot(app, requireExists)
            if nargin < 2
                requireExists = false;
            end
            root = '';
            try
                if ~isempty(app.RuntimeClassifierSource) && isa(app.RuntimeClassifierSource, 'classi') && ...
                        ~isempty(strtrim(char(string(app.RuntimeClassifierSource.path))))
                    root = char(string(app.RuntimeClassifierSource.path));
                end
            catch
                root = '';
            end
            if ~isempty(root)
                if logical(requireExists) && exist(root, 'dir') ~= 7
                    mkdir(root);
                end
                return;
            end
            try
                nodes = app.Data.nodes;
                for i = 1:numel(nodes)
                    if ~strcmpi(char(string(getField(app, nodes(i), 'type', ''))), 'classifier')
                        continue;
                    end
                    p = getField(app, nodes(i), 'params', struct());
                    if isstruct(p) && isfield(p, 'modulePath') && ~isempty(p.modulePath)
                        root = char(string(p.modulePath));
                        break;
                    end
                end
            catch
                root = '';
            end
            if ~isempty(root) && logical(requireExists) && exist(root, 'dir') ~= 7
                mkdir(root);
            end
        end

        function runId = suggestNextClassifierRunId(app, classiPath, templateId) %#ok<INUSD>
            base = matlab.lang.makeValidName(char(string(templateId)));
            if isempty(base)
                base = 'classifier_validation';
            end
            runParent = fullfile(classiPath, 'pipeline_runs');
            if exist(runParent, 'dir') ~= 7
                runId = [base '_1'];
                return;
            end
            n = 1;
            while exist(fullfile(runParent, [base '_' num2str(n)]), 'dir') == 7
                n = n + 1;
            end
            runId = [base '_' num2str(n)];
        end

        function ctx = attachRunArtifactPathsToContext(app, ctx, runObj) %#ok<INUSD>
            if ~isstruct(ctx)
                ctx = struct();
            end
            if ~isfield(ctx, 'run') || ~isstruct(ctx.run)
                ctx.run = struct();
            end
            if ~isfield(ctx, 'io') || ~isstruct(ctx.io)
                ctx.io = struct();
            end
            if ~isfield(ctx, 'store') || ~isstruct(ctx.store)
                ctx.store = struct();
            end
            runPath = '';
            try
                [runPath, ~] = runObj.getPath;
            catch
                runPath = '';
            end
            if isempty(runPath)
                return;
            end
            eventLogPath = fullfile(runPath, 'run_events.jsonl');
            ctx.runId = char(string(runObj.runId));
            ctx.run.runId = char(string(runObj.runId));
            ctx.run.path = runPath;
            ctx.run.runPath = runPath;
            ctx.run.eventLogPath = eventLogPath;
            ctx.io.eventLogPath = eventLogPath;
            ctx.store.runPath = runPath;
            ctx.store.eventLogPath = eventLogPath;
        end

        function savePipelineRunAndProject(app, runObj, progressDlg, message, saveProject)
            if nargin < 3
                progressDlg = [];
            end
            if nargin < 4 || isempty(message)
                message = 'Saving pipeline run...';
            end
            if nargin < 5 || isempty(saveProject)
                saveProject = true;
            end
            updateRunSaveProgress(app, progressDlg, message);
            saveTimer = tic;
            pipelineRunSave(runObj);
            jsonSec = toc(saveTimer);
            updateRunSaveProgress(app, progressDlg, 'Attaching run to project...');
            projectChanged = attachCurrentRunToProject(app, runObj);
            markRunDirty(app, false);
            if logical(saveProject) && projectChanged && ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                updateRunSaveProgress(app, progressDlg, 'Saving project state...');
                shallowSave(app.CurrentProject, 'shallowObj');
            end
            updateRunSaveProgress(app, progressDlg, 'Refreshing main application tree...');
            treeTimer = tic;
            publishCurrentProjectForTreeRefresh(app, runObj);
            fprintf('Run save timings: JSON %.3fs, tree refresh %.3fs, total %.3fs.\n', ...
                jsonSec, toc(treeTimer), toc(saveTimer));
        end

        function changed = attachCurrentRunToProject(app, runObj)
            changed = false;
            if runtimeStartsFromClassifier(app)
                return;
            end
            if isempty(runObj) || ~isa(runObj, 'pipelineRun') || isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow')
                return;
            end
            shallowObj = app.CurrentProject;
            if ~isfield(shallowObj.processing, 'pipelineRun') || isempty(shallowObj.processing.pipelineRun)
                shallowObj.processing.pipelineRun = pipelineRun.empty;
                changed = true;
            end
            runId = char(string(runObj.runId));
            idx = [];
            try
                ids = arrayfun(@(r)char(string(r.runId)), shallowObj.processing.pipelineRun, 'UniformOutput', false);
                idx = find(strcmp(ids, runId), 1);
            catch
                idx = [];
            end
            if isempty(idx)
                try
                    paths = arrayfun(@(r)char(string(r.path)), shallowObj.processing.pipelineRun, 'UniformOutput', false);
                    idx = find(strcmp(paths, char(string(runObj.path))), 1);
                catch
                    idx = [];
                end
            end
            if isempty(idx)
                shallowObj.processing.pipelineRun(end+1) = runObj;
                changed = true;
            else
                changed = runAttachmentChanged(app, shallowObj.processing.pipelineRun(idx), runObj);
                if changed
                    shallowObj.processing.pipelineRun(idx) = runObj;
                end
            end
            if changed
                app.CurrentProject = shallowObj;
            end
            if changed && ~isempty(app.CurrentProjectVarName)
                try
                    assignin('base', char(string(app.CurrentProjectVarName)), shallowObj);
                catch
                end
            end
        end

        function changed = runAttachmentChanged(app, oldRun, newRun) %#ok<INUSD>
            changed = true;
            try
                if isempty(oldRun) || ~isa(oldRun, 'pipelineRun') || isempty(newRun) || ~isa(newRun, 'pipelineRun')
                    return;
                end
                keys = {'runId', 'path', 'status', 'updatedAt', 'templateId', 'templatePath', 'projectPath', 'projectName'};
                for ii = 1:numel(keys)
                    key = keys{ii};
                    if ~strcmp(localRunPropText(app, oldRun, key), localRunPropText(app, newRun, key))
                        return;
                    end
                end
                if ~isequaln(oldRun.pipelineRef, newRun.pipelineRef)
                    return;
                end
                if ~isequaln(oldRun.targetRef, newRun.targetRef)
                    return;
                end
                changed = false;
            catch
                changed = true;
            end
        end

        function value = localRunPropText(app, runObj, propName) %#ok<INUSD>
            value = '';
            try
                if isprop(runObj, propName)
                    value = char(string(runObj.(propName)));
                end
            catch
                value = '';
            end
        end

        function publishCurrentProjectForTreeRefresh(app, runObj)
            if runtimeStartsFromClassifier(app) || isempty(app.CurrentProject) || ...
                    ~isa(app.CurrentProject, 'shallow') || exist('detecdiv_event', 'file') ~= 2
                if exist('detecdiv_event', 'file') == 2 && ~isempty(runObj) && isa(runObj, 'pipelineRun')
                    classiPath = '';
                    try
                        if isprop(runObj,'targetRef') && isstruct(runObj.targetRef) && isfield(runObj.targetRef,'classiPath')
                            classiPath = char(string(runObj.targetRef.classiPath));
                        end
                    catch
                        classiPath = '';
                    end
                    if isempty(classiPath)
                        try
                            classiPath = classifierScopedRunRoot(app, false);
                        catch
                            classiPath = '';
                        end
                    end
                    if ~isempty(classiPath)
                        payload = struct();
                        payload.kind = 'pipelineRun';
                        payload.action = 'saved';
                        payload.source = 'pipeline2';
                        payload.runId = char(string(runObj.runId));
                        payload.runPath = char(string(runObj.path));
                        payload.classiPath = classiPath;
                        payload.projectObj = [];
                        payload.projectVarName = '';
                        payload.projectMatPath = '';
                        payload.projectPath = '';
                        payload.projectName = '';
                        try
                            detecdiv_event('emit', 'pipelineRunSaved', payload);
                            detecdiv_event('emit', 'workspaceChanged', payload);
                        catch
                        end
                    end
                end
                return;
            end
            payload = struct();
            payload.kind = 'pipelineRun';
            payload.action = 'saved';
            payload.source = 'pipeline2';
            payload.projectObj = app.CurrentProject;
            payload.projectVarName = char(string(app.CurrentProjectVarName));
            payload.runId = '';
            payload.runPath = '';
            payload.projectMatPath = '';
            payload.projectPath = '';
            payload.projectName = '';
            if ~isempty(runObj) && isa(runObj, 'pipelineRun')
                payload.runId = char(string(runObj.runId));
                payload.runPath = char(string(runObj.path));
            end
            try
                [pth, file] = app.CurrentProject.getPath;
                payload.projectMatPath = fullfile(pth, [file '.mat']);
                payload.projectPath = fullfile(pth, file);
                payload.projectName = char(string(file));
            catch
            end
            try
                detecdiv_event('emit', 'pipelineRunSaved', payload);
                detecdiv_event('emit', 'workspaceChanged', payload);
            catch
            end
        end

        function updateRunSaveProgress(app, progressDlg, message, value) %#ok<INUSD>
            if nargin < 4
                value = [];
            end
            try
                if ~isempty(progressDlg) && isvalid(progressDlg)
                    progressDlg.Message = char(string(message));
                    if ~isempty(value)
                        try
                            progressDlg.Indeterminate = 'off';
                        catch
                        end
                        progressDlg.Value = double(value);
                    end
                    drawnow limitrate nocallbacks;
                end
            catch
            end
            try
                setRuntimeStatus(app, char(string(message)));
            catch
            end
        end

        function markCurrentRunAsSeed(app, runObj)
            app.CurrentRunIsSeed = true;
            app.CurrentRunSourceId = '';
            try
                app.CurrentRunSourceId = char(string(runObj.runId));
            catch
            end
            if ~isempty(app.CurrentRunSourceId)
                setRuntimeStatus(app, sprintf('New run from existing run: %s\nParameters copied; next Run/Save creates a distinct run.', app.CurrentRunSourceId));
            end
            try
                suggested = suggestNextRunIdForUi(app);
                app.TemplateidEditField.Value = suggested;
                app.RuntimeValues.runId = suggested;
            catch
            end
            try
                app.ResumeoptionsDropDown.Value = 'Restart from scratch';
                app.RuntimeValues.outputPolicyUserChosen = false;
                applyRecommendedOutputPolicyForResume(app);
            catch
            end
            app.RunButton.Text = 'Run !';
        end

        function runId = runtimeRunIdFromUi(app)
            runId = '';
            try
                if isfield(app.RuntimeValues, 'runId') && ~isempty(app.RuntimeValues.runId)
                    runId = char(string(app.RuntimeValues.runId));
                elseif ~isempty(app.TemplateidEditField) && isvalid(app.TemplateidEditField)
                    runId = char(string(app.TemplateidEditField.Value));
                end
            catch
                runId = '';
            end
            runId = matlab.lang.makeValidName(strtrim(runId));
        end

        function runId = suggestNextRunIdForUi(app)
            ref = buildPipelineRef(app);
            templateId = char(string(getField(app, ref, 'id', 'pipeline')));
            if isempty(strtrim(templateId))
                templateId = 'pipeline';
            end
            names = {};
            try
                runRoot = fullfile(currentProjectFolder(app), 'pipeline');
                if exist(runRoot, 'dir') == 7
                    d = dir(runRoot);
                    d = d([d.isdir]);
                    names = setdiff({d.name}, {'.','..'}, 'stable');
                end
            catch
                names = {};
            end
            n = 1;
            runId = sprintf('%s_%d', templateId, n);
            while any(strcmp(names, runId))
                n = n + 1;
                runId = sprintf('%s_%d', templateId, n);
            end
        end

        function ok = saveCurrentRun(app, forceAs)
            ok = false;
            if nargin < 2
                forceAs = false;
            end
            if ~ensureCurrentProjectForRun(app)
                return;
            end
            requestedRunId = '';
            if forceAs
                defaultRunId = suggestNextRunIdForUi(app);
                answer = inputdlg({'New run id:'}, 'Save run as', [1 48], {defaultRunId});
                if isempty(answer)
                    return;
                end
                requestedRunId = strtrim(char(string(answer{1})));
                if isempty(requestedRunId)
                    return;
                end
            end
            d = [];
            try
                d = uiprogressdlg(app.UIFigure, 'Title', 'Save run', ...
                    'Message', 'Preparing run save...', ...
                    'Value', 0.05, 'Cancelable', 'off');
                ddProgressGuard8 = onCleanup(@()detecdiv_close_progress(d)); %#ok<NASGU>
                drawnow nocallbacks;
                drawnow nocallbacks;
            catch
                d = [];
            end
            cleanupObj = onCleanup(@()closeProgressDialog(app, d)); %#ok<NASGU>
            try
                updateRunSaveProgress(app, d, 'Collecting runtime context...', 0.15);
                ctx = buildRunContext(app);
                updateRunSaveProgress(app, d, 'Creating pipeline run object...', 0.25);
                runObj = createOrUpdateCurrentRun(app, ctx, 'preflight', forceAs, requestedRunId);
                updateRunSaveProgress(app, d, 'Writing run JSON...', 0.55);
                logRunEvent(app, runObj, 'Run parameters saved from pipeline2.', 'pipeline2');
                savePipelineRunAndProject(app, runObj, d, 'Saving run and project link...', true);
                updateRunSaveProgress(app, d, 'Run saved.', 1);
                ok = true;
                if runtimeStartsFromClassifier(app)
                    setRuntimeStatus(app, ['Run saved under source classifier: ' fullfile(runObj.path, 'run.json')]);
                else
                    setRuntimeStatus(app, ['Run saved and attached to project: ' fullfile(runObj.path, 'run.json')]);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Save run', 'Icon', 'error');
            end
        end

        function [ok, runJsonPath] = saveCurrentRunSnapshotIfProjectAvailable(app)
            ok = false;
            runJsonPath = '';
            if isempty(app.CurrentProject) || ~isa(app.CurrentProject, 'shallow') || isempty(app.CurrentPipelinePath)
                return;
            end
            try
                ctx = buildRunContext(app);
                runObj = createOrUpdateCurrentRun(app, ctx, 'preflight');
                logRunEvent(app, runObj, 'Run parameters saved with pipeline template.', 'pipeline2');
                savePipelineRunAndProject(app, runObj, [], 'Saving run JSON snapshot...', false);
                runJsonPath = fullfile(runObj.path, 'run.json');
                ok = true;
            catch ME
                try
                    app.PipelineandRuncheckreportLabel.Text = [app.PipelineandRuncheckreportLabel.Text newline newline ...
                        'Run snapshot save failed:' newline ME.message];
                catch
                end
            end
        end

        function openCurrentRunArtifact(app, kind)
            if nargin < 2 || isempty(kind)
                kind = 'folder';
            end
            runObj = app.CurrentRun;
            if isempty(runObj) || ~isa(runObj, 'pipelineRun')
                if ~saveCurrentRun(app, false)
                    return;
                end
                runObj = app.CurrentRun;
            else
                try
                    pipelineRunSave(runObj);
                catch
                end
            end
            [runPath, ~] = runObj.getPath;
            if isempty(runPath) || ~exist(runPath, 'dir')
                uialert(app.UIFigure, 'No current run folder is available yet.', 'Open run artifact', 'Icon', 'warning');
                return;
            end
            switch lower(char(string(kind)))
                case 'log'
                    target = fullfile(runPath, 'run_log.txt');
                case 'params'
                    target = fullfile(runPath, 'run_params.json');
                case 'summary'
                    target = fullfile(runPath, 'run_summary.txt');
                case 'review'
                    target = fullfile(runPath, 'run_review.txt');
                    if exist(target, 'file') ~= 2
                        try
                            pipelineRunReview(runObj, 'Write', true);
                        catch
                        end
                    end
                case 'events'
                    target = fullfile(runPath, 'run_events.jsonl');
                otherwise
                    target = runPath;
            end
            if ~exist(target, 'file') && ~exist(target, 'dir')
                try
                    pipelineRunSave(runObj);
                catch ME
                    uialert(app.UIFigure, ME.message, 'Open run artifact', 'Icon', 'error');
                    return;
                end
            end
            openPathInSystem(app, target);
        end

        function showCurrentRunReview(app)
            runObj = app.CurrentRun;
            if isempty(runObj) || ~isa(runObj, 'pipelineRun')
                if ~saveCurrentRun(app, false)
                    return;
                end
                runObj = app.CurrentRun;
            else
                try
                    pipelineRunSave(runObj);
                catch
                end
            end
            try
                pipelineRunReview(runObj, 'Write', true);
            catch
            end
            try
                pipelineRunInspector(runObj, app.CurrentProject);
            catch ME
                uialert(app.UIFigure, ME.message, 'Review run', 'Icon', 'error');
            end
        end

        function showCurrentRunLog(app)
            runObj = app.CurrentRun;
            if isempty(runObj) || ~isa(runObj, 'pipelineRun')
                if ~saveCurrentRun(app, false)
                    return;
                end
                runObj = app.CurrentRun;
            else
                try
                    pipelineRunSave(runObj);
                catch
                end
            end
            [runPath, ~] = runObj.getPath;
            logFile = fullfile(runPath, 'run_log.txt');
            if exist(logFile, 'file') ~= 2
                try
                    pipelineRunSave(runObj);
                catch
                end
            end
            txt = {'No run log available yet.'};
            if exist(logFile, 'file') == 2
                try
                    txt = splitlines(fileread(logFile));
                    txt = cellstr(txt(:));
                catch ME
                    txt = {['Unable to read run log: ' ME.message]};
                end
            end

            fig = uifigure('Name', 'Pipeline run log', 'Position', [160 120 920 620]);
            grid = uigridlayout(fig, [3 4]);
            grid.RowHeight = {24, '1x', 32};
            grid.ColumnWidth = {'1x', 120, 120, 120};
            grid.Padding = [12 12 12 12];
            grid.RowSpacing = 8;
            grid.ColumnSpacing = 8;

            titleLabel = uilabel(grid, 'Text', ['Run log: ' logFile], 'Interpreter', 'none');
            titleLabel.Layout.Row = 1;
            titleLabel.Layout.Column = [1 4];

            area = uitextarea(grid, 'Editable', 'off', 'Value', txt);
            area.Layout.Row = 2;
            area.Layout.Column = [1 4];

            runFileLabel = uilabel(grid, 'Text', fullfile(runPath, 'run.json'), 'Interpreter', 'none', 'FontColor', [0.35 0.35 0.35]);
            runFileLabel.Layout.Row = 3;
            runFileLabel.Layout.Column = 1;
            btnFolder = uibutton(grid, 'push', 'Text', 'Open folder', ...
                'ButtonPushedFcn', @(~,~)openPathInSystem(app, runPath));
            btnFolder.Layout.Row = 3;
            btnFolder.Layout.Column = 2;
            btnFile = uibutton(grid, 'push', 'Text', 'Open log file', ...
                'ButtonPushedFcn', @(~,~)openPathInSystem(app, logFile));
            btnFile.Layout.Row = 3;
            btnFile.Layout.Column = 3;
            btnClose = uibutton(grid, 'push', 'Text', 'Close', ...
                'ButtonPushedFcn', @(~,~)delete(fig));
            btnClose.Layout.Row = 3;
            btnClose.Layout.Column = 4;
        end

        function openPathInSystem(app, targetPath) %#ok<INUSD>
            targetPath = char(string(targetPath));
            try
                if ispc
                    winopen(targetPath);
                elseif ismac
                    system(['open "' strrep(targetPath, '"', '\"') '" &']);
                else
                    system(['xdg-open "' strrep(targetPath, '"', '\"') '" &']);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Open path', 'Icon', 'error');
            end
        end

        function logRunEvent(app, runObj, message, category) %#ok<INUSD>
            if nargin < 4 || isempty(category)
                category = 'pipeline2';
            end
            if isempty(runObj) || ~isa(runObj, 'pipelineRun')
                return;
            end
            try
                runObj.log(message, category);
            catch
            end
        end

        function ctx = attachRunCancellationAndProgress(app, ctx, runObj, progressDlg) %#ok<INUSD>
            tokenFile = '';
            try
                [runPath, ~] = runObj.getPath;
                if ~isempty(runPath)
                    if exist(runPath, 'dir') ~= 7
                        mkdir(runPath);
                    end
                    tokenFile = fullfile(runPath, 'cancel.request');
                    if exist(tokenFile, 'file') == 2
                        delete(tokenFile);
                    end
                end
            catch
                tokenFile = '';
            end
            ctx.cancel = struct('tokenFile', tokenFile);
            try
                if isfield(ctx,'run') && isstruct(ctx.run) && isfield(ctx.run,'control') && isstruct(ctx.run.control)
                    ctx.run.control.backend = 'local';
                    ctx.run.control.cancelMode = 'file_token';
                    ctx.run.control.cancelTokenFile = tokenFile;
                end
            catch
            end
            ctx.progress = struct('startedAt', char(datetime('now')), 'value', 0, 'message', 'Run queued');
            if nargin >= 4 && ~isempty(progressDlg)
                ctx.progressDlg = progressDlg;
            end
        end

        function ctx = stripTransientRunContext(app, ctx) %#ok<INUSD>
            if ~isstruct(ctx)
                return;
            end
            heavyFields = {'shallow', 'shallowObj', 'project', 'projectObj', ...
                'fovList', 'roiList', 'rois', 'classifierObj', 'classiObj', ...
                'progressDlg', 'cancel'};
            for iField = 1:numel(heavyFields)
                name = heavyFields{iField};
                try
                    if isfield(ctx, name)
                        ctx = rmfield(ctx, name);
                    end
                catch
                end
            end
            try
                if isfield(ctx,'progress') && isstruct(ctx.progress)
                    if isfield(ctx.progress,'startedTic')
                        ctx.progress = rmfield(ctx.progress, 'startedTic');
                    end
                    if isfield(ctx.progress,'nodeStartTic')
                        ctx.progress = rmfield(ctx.progress, 'nodeStartTic');
                    end
                end
            catch
            end
            try
                if isfield(ctx,'store') && isstruct(ctx.store) && isfield(ctx.store,'classifierRuntime')
                    ctx.store = rmfield(ctx.store, 'classifierRuntime');
                end
            catch
            end
        end

        function tf = isPipelineCancelException(app, ME) %#ok<INUSD>
            tf = false;
            try
                ids = string(ME.identifier);
                for iCause = 1:numel(ME.cause)
                    ids(end+1) = string(ME.cause{iCause}.identifier); %#ok<AGROW>
                end
                tf = any(strcmp(ids, "runPipeline:Cancelled")) || contains(string(ME.message), "cancelled by user", 'IgnoreCase', true);
            catch
                tf = strcmp(ME.identifier, 'runPipeline:Cancelled');
            end
        end

        function tf = isHubProjectIndexQueuedException(app, ME) %#ok<INUSD>
            tf = false;
            try
                ids = string(ME.identifier);
                for iCause = 1:numel(ME.cause)
                    ids(end+1) = string(ME.cause{iCause}.identifier); %#ok<AGROW>
                end
                tf = any(strcmp(ids, "detecdiv_hub_submit_pipeline_run:ProjectIndexQueued")) || ...
                    any(strcmp(ids, "detecdiv_hub_ensure_project:IndexQueued")) || ...
                    contains(string(ME.message), "queued for Hub indexing", 'IgnoreCase', true);
            catch
                tf = strcmp(ME.identifier, 'detecdiv_hub_submit_pipeline_run:ProjectIndexQueued');
            end
        end

        function tf = isHubProjectLockedException(app, ME) %#ok<INUSD>
            tf = false;
            try
                ids = string(ME.identifier);
                for iCause = 1:numel(ME.cause)
                    ids(end+1) = string(ME.cause{iCause}.identifier); %#ok<AGROW>
                end
                tf = any(strcmp(ids, "detecdiv_hub_submit_pipeline_run:ProjectLocked")) || ...
                    (any(strcmp(ids, "detecdiv_hub_request:HTTP409")) && ...
                    contains(string(ME.message), "project is locked", 'IgnoreCase', true));
            catch
                tf = strcmp(ME.identifier, 'detecdiv_hub_submit_pipeline_run:ProjectLocked');
            end
        end

        function runObj = annotateHubRunControl(app, runObj, job) %#ok<INUSD>
            try
                if ~isstruct(runObj.ctx)
                    runObj.ctx = struct();
                end
                if ~isfield(runObj.ctx,'run') || ~isstruct(runObj.ctx.run)
                    runObj.ctx.run = struct();
                end
                if ~isfield(runObj.ctx.run,'control') || ~isstruct(runObj.ctx.run.control)
                    runObj.ctx.run.control = struct();
                end
                runObj.ctx.run.control.backend = 'hub';
                runObj.ctx.run.control.cancelPolicy = 'cooperative';
                runObj.ctx.run.control.cancelMode = 'hub_job_cancel';
                runObj.ctx.run.control.progressGranularity = 'roi';
                runObj.ctx.run.control.safeStopPoint = 'between_rois';
                runObj.ctx.run.control.cancelEndpoint = '/pipeline-runs/{job_id}/cancel';
                runObj.ctx.run.control.statusEndpoint = '/pipeline-runs/{job_id}';
                if isstruct(job) && isfield(job,'id') && ~isempty(job.id)
                    runObj.ctx.run.control.jobId = char(string(job.id));
                end
            catch
            end
        end

        function tf = isRunCancellationButtonActive(app)
            tf = ~isempty(strtrim(app.ActiveRunMode));
        end

        function finishRunLaunch(app)
            app.RunLaunchInProgress = false;
            if app.RuntimeModeUnlocked && ~app.BatchPrototypeMode && ...
                    ~app.HubRunUiLocked && isempty(app.ActiveRunMode)
                app.RunButton.Enable = 'on';
            else
                app.RunButton.Enable = 'off';
            end
        end

        function resumed = resumeExistingHubRun(app, runObj, hub)
            resumed = false;
            job = detecdiv_hub_find_active_pipeline_run(runObj, app.CurrentProject, hub);
            if isempty(job), return; end
            % Reattach in memory; the worker owns the persisted run now.
            if ~isstruct(runObj.ctx), runObj.ctx = struct(); end
            if ~isfield(runObj.ctx,'hub') || ~isstruct(runObj.ctx.hub)
                runObj.ctx.hub = hub;
            end
            runObj.ctx.hub.job_id = char(string(job.id));
            runObj.ctx.hub.status = char(string(job.status));
            runObj.status = ['hub_' char(string(job.status))];
            runObj = annotateHubRunControl(app, runObj, job);
            app.CurrentRun = runObj;
            app.CurrentRunIsSeed = false;
            attachCurrentRunToProject(app, runObj);
            startHubRunMonitor(app, runObj, job);
            message = sprintf('Run already submitted: %s\nFollowing existing Hub job %s in Monitor. No new job was submitted.', ...
                char(string(runObj.runId)), char(string(job.id)));
            setRuntimeStatus(app, message);
            appendMonitorConsole(app, message);
            resumed = true;
        end

        function startLocalRunControl(app, runObj) %#ok<INUSD>
            app.ActiveRunMode = 'local_process';
            app.ActiveRunCancelRequested = false;
            app.RunButton.Text = 'Run !';
            app.RunButton.Enable = 'off';
            app.CancelrunButton.Text = 'Cancel run...';
            app.CancelrunButton.Enable = 'on';
            resetRunMonitor(app, 'Local MATLAB worker queued.');
            app.MainTabGroup.SelectedTab = app.MonitorTab;
            applyHubRunUiLock(app, true);
            drawnow limitrate;
        end

        function stopActiveRunControl(app, buttonText)
            if nargin < 2 || isempty(buttonText)
                buttonText = 'Run !';
            end
            stopHubRunMonitor(app);
            app.ActiveRunMode = '';
            app.ActiveRunCancelRequested = false;
            app.RunButton.Text = char(string(buttonText));
            app.RunButton.Enable = ternary(app, ~app.RunLaunchInProgress && app.RuntimeModeUnlocked, 'on', 'off');
            app.CancelrunButton.Text = 'Cancel run...';
            app.CancelrunButton.Enable = 'off';
            drawnow limitrate;
        end

        function requestActiveRunCancellation(app)
            mode = lower(strtrim(app.ActiveRunMode));
            if isempty(mode)
                return;
            end
            if app.ActiveRunCancelRequested
                return;
            end
            app.ActiveRunCancelRequested = true;
            app.CancelrunButton.Text = 'Cancelling...';
            app.CancelrunButton.Enable = 'off';
            appendMonitorConsole(app, '[control] Cancellation requested; waiting for a safe stop point.');
            drawnow limitrate;
            switch mode
                case {'local','local_process'}
                    requestLocalRunCancellation(app);
                case 'hub'
                    requestHubRunCancellation(app);
                otherwise
                    app.ActiveRunCancelRequested = false;
                    app.CancelrunButton.Text = 'Cancel run...';
                    app.CancelrunButton.Enable = 'off';
            end
        end

        function requestLocalRunCancellation(app)
            tokenFile = '';
            try
                if ~isempty(app.CurrentRun) && isa(app.CurrentRun, 'pipelineRun') && isstruct(app.CurrentRun.ctx) && ...
                        isfield(app.CurrentRun.ctx, 'cancel') && isstruct(app.CurrentRun.ctx.cancel) && ...
                        isfield(app.CurrentRun.ctx.cancel, 'tokenFile') && ~isempty(app.CurrentRun.ctx.cancel.tokenFile)
                    tokenFile = char(string(app.CurrentRun.ctx.cancel.tokenFile));
                end
            catch
                tokenFile = '';
            end
            if isempty(tokenFile)
                try
                    [runPath, ~] = app.CurrentRun.getPath;
                    tokenFile = fullfile(runPath, 'cancel.request');
                catch
                    tokenFile = '';
                end
            end
            try
                if ~isempty(tokenFile)
                    tokenDir = fileparts(tokenFile);
                    if ~isempty(tokenDir) && exist(tokenDir, 'dir') ~= 7
                        mkdir(tokenDir);
                    end
                    fid = fopen(tokenFile, 'w');
                    if fid > 0
                        fprintf(fid, 'cancel requested at %s\n', char(datetime('now')));
                        fclose(fid);
                    end
                end
                setRuntimeStatus(app, 'Stop requested. Waiting for the current safe point...');
                try
                    logRunEvent(app, app.CurrentRun, 'Local run cancellation requested from Monitor.', 'pipeline2');
                catch
                end
            catch ME
                app.ActiveRunCancelRequested = false;
                app.CancelrunButton.Text = 'Cancel run...';
                app.CancelrunButton.Enable = 'on';
                uialert(app.UIFigure, ME.message, 'Stop run failed', 'Icon', 'error');
            end
        end

        function requestHubRunCancellation(app)
            jobId = currentHubRunJobId(app);
            if isempty(jobId)
                app.ActiveRunCancelRequested = false;
                app.CancelrunButton.Text = 'Cancel run...';
                app.CancelrunButton.Enable = 'off';
                uialert(app.UIFigure, 'This run has no hub job id.', 'Cancel hub run', 'Icon', 'warning');
                return;
            end
            try
                job = detecdiv_hub_cancel_pipeline_run(jobId);
                updateCurrentRunFromHubJob(app, job);
                setRuntimeStatus(app, formatHubRunStatusText(app, job, app.CurrentRun));
                appendRunReport(app, ['Hub cancel requested: ' char(string(getField(app, job, 'status', 'cancelling')))], job);
                try
                    logRunEvent(app, app.CurrentRun, ['Hub run cancellation requested for job ' jobId '.'], 'pipeline2');
                    pipelineRunSave(app.CurrentRun);
                catch
                end
                statusText = char(string(getField(app, job, 'status', 'cancelling')));
                if any(strcmpi(statusText, {'done','failed','cancelled'}))
                    stopActiveRunControl(app, terminalButtonText(app, statusText));
                    setRuntimeStatus(app, formatHubRunStatusText(app, job, app.CurrentRun));
                end
            catch ME
                app.ActiveRunCancelRequested = false;
                app.CancelrunButton.Text = 'Cancel run...';
                app.CancelrunButton.Enable = 'on';
                uialert(app.UIFigure, ME.message, 'Cancel hub run failed', 'Icon', 'error');
            end
        end

        function startHubRunMonitor(app, runObj, job)
            jobId = hubJobIdFromRunOrJob(app, runObj, job);
            if isempty(jobId)
                return;
            end
            stopHubRunMonitor(app);
            app.ActiveRunMode = 'hub';
            app.ActiveRunCancelRequested = false;
            app.HubRunMonitorJobId = jobId;
            app.HubRunMonitorLastStatus = char(string(getField(app, job, 'status', 'submitted')));
            app.RunButton.Text = 'Run !';
            app.RunButton.Enable = 'off';
            app.CancelrunButton.Text = 'Cancel run...';
            app.CancelrunButton.Enable = 'on';
            resetRunMonitor(app, ['Hub job submitted: ' jobId]);
            updateRunMonitorFromHubJob(app, job);
            app.MainTabGroup.SelectedTab = app.MonitorTab;
            applyHubRunUiLock(app, true);
            try
                startHubRunFileMonitor(app, runObj);
            catch ME
                appendMonitorConsole(app, ['[monitor] Shared-file stream unavailable: ' ME.message]);
            end
            try
                startHubRunStatusWorker(app, jobId, false, Inf);
            catch ME
                % A monitor connection failure cannot make the accepted job
                % safe to submit again. Keep its identity and launch lock.
                appendMonitorConsole(app, ['[monitor] Status connection unavailable: ' ME.message]);
                setRuntimeStatus(app, ['Hub job accepted: ' jobId '. Monitor connection unavailable; use Refresh status to retry.']);
            end
        end

        function stopHubRunMonitor(app)
            token = app.HubRunMonitorToken;
            app.HubRunMonitorToken = '';
            app.HubRunMonitorJobId = '';
            app.HubRunMonitorLastStatus = '';
            applyHubRunUiLock(app, false);
            try
                if ~isempty(token)
                    detecdiv_hub_broker('unregister', token);
                end
            catch
            end
            try
                if ~isempty(app.HubRunFileMonitorFuture) && isvalid(app.HubRunFileMonitorFuture)
                    cancel(app.HubRunFileMonitorFuture);
                end
            catch
            end
            try
                if ~isempty(app.HubRunFileMonitorEventListener) && isvalid(app.HubRunFileMonitorEventListener)
                    delete(app.HubRunFileMonitorEventListener);
                end
            catch
            end
            app.HubRunFileMonitorFuture = [];
            app.HubRunFileMonitorEventQueue = [];
            app.HubRunFileMonitorEventListener = [];
            app.HubRunFileMonitorResultQueue = [];
        end

        function startHubRunFileMonitor(app, runObj)
            runPath = '';
            try
                runPath = char(string(runObj.path));
            catch
            end
            if isempty(runPath)
                appendMonitorConsole(app, '[monitor] Hub run path unavailable; using status polling only.');
                return;
            end
            consolePath = fullfile(runPath, 'worker_console.log');
            progressPath = fullfile(runPath, 'progress.json');
            neverResultPath = fullfile(runPath, '.hub_monitor_result_never.json');
            eventQueue = parallel.pool.DataQueue;
            resultQueue = parallel.pool.DataQueue;
            eventListener = afterEach(eventQueue, ...
                @(payload)handleLocalRunMonitorEvent(app, payload));
            future = parfeval(backgroundPool, ...
                @detecdiv_local_watch_pipeline_result, 0, ...
                neverResultPath, resultQueue, 7 * 24 * 60 * 60, ...
                consolePath, progressPath, eventQueue);
            app.HubRunFileMonitorEventQueue = eventQueue;
            app.HubRunFileMonitorResultQueue = resultQueue;
            app.HubRunFileMonitorEventListener = eventListener;
            app.HubRunFileMonitorFuture = future;
        end

        function resetRunMonitor(app, message)
            if nargin < 2 || isempty(message)
                message = 'Preparing run...';
            end
            stamp = char(datetime('now', 'Format', 'HH:mm:ss'));
            app.ConsoleTextArea.Value = {sprintf('[%s] %s', stamp, char(string(message)))};
            app.MonitorLastProgressConsoleSignature = '';
            app.MonitorLastHubConsoleText = '';
            app.MonitorHasModuleProgress = false;
            app.MonitorRunStartTic = tic;
            app.MonitorEtaSeconds = NaN;
            updateRunMonitorProgress(app, 0, char(string(message)));
        end

        function appendMonitorConsole(app, textValue)
            if isempty(textValue)
                return;
            end
            try
                existing = cellstr(string(app.ConsoleTextArea.Value));
            catch
                existing = {};
            end
            incoming = regexp(char(string(textValue)), '\r\n|\r|\n', 'split');
            if ~isempty(incoming) && isempty(incoming{end})
                incoming(end) = [];
            end
            if isempty(incoming)
                return;
            end
            lines = [existing(:); incoming(:)];
            maxLines = 2000;
            if numel(lines) > maxLines
                lines = lines(end-maxLines+1:end);
            end
            app.ConsoleTextArea.Value = lines;
            drawnow limitrate nocallbacks;
            scroll(app.ConsoleTextArea, 'bottom');
        end

        function updateRunMonitorProgress(app, value, message, suppliedEta)
            if nargin < 2 || isempty(value) || ~isfinite(double(value))
                value = app.MonitorLastProgress;
            end
            if nargin < 4
                suppliedEta = '';
            end
            value = max(0, min(1, double(value)));
            app.MonitorLastProgress = value;
            try
                if ~isempty(app.MonitorProgressGauge) && isvalid(app.MonitorProgressGauge)
                    app.MonitorProgressGauge.Value = value;
                end
            catch
            end
            etaText = monitorEtaText(app, value, suppliedEta);
            if nargin >= 3 && ~isempty(message)
                app.ProgressionStatusLabel.Text = sprintf('%3.0f%%  |  ETA %s  |  %s', ...
                    100 * value, etaText, char(string(message)));
            else
                app.ProgressionStatusLabel.Text = sprintf('%3.0f%%  |  ETA %s', ...
                    100 * value, etaText);
            end
            drawnow limitrate nocallbacks;
        end

        function etaText = monitorEtaText(app, value, suppliedEta)
            suppliedEta = strtrim(char(string(suppliedEta)));
            if ~isempty(suppliedEta)
                etaText = suppliedEta;
                return;
            end
            if value >= 1
                app.MonitorEtaSeconds = 0;
                etaText = '0 s';
                return;
            end
            if value <= 0 || isempty(app.MonitorRunStartTic)
                etaText = '--';
                return;
            end
            try
                elapsedSeconds = toc(app.MonitorRunStartTic);
            catch
                etaText = '--';
                return;
            end
            if ~isfinite(elapsedSeconds) || elapsedSeconds < 2 || value < 0.005
                etaText = '--';
                return;
            end
            rawEtaSeconds = elapsedSeconds * (1 - value) / value;
            if ~isfinite(app.MonitorEtaSeconds)
                app.MonitorEtaSeconds = rawEtaSeconds;
            else
                % Smooth fluctuations caused by uneven node/frame durations.
                app.MonitorEtaSeconds = 0.75 * app.MonitorEtaSeconds + ...
                    0.25 * rawEtaSeconds;
            end
            etaText = formatMonitorDuration(app, app.MonitorEtaSeconds);
        end

        function textValue = formatMonitorDuration(~, secondsValue)
            secondsValue = max(0, round(double(secondsValue)));
            daysValue = floor(secondsValue / 86400);
            hoursValue = floor(mod(secondsValue, 86400) / 3600);
            minutesValue = floor(mod(secondsValue, 3600) / 60);
            secondsValue = mod(secondsValue, 60);
            if daysValue > 0
                textValue = sprintf('%d j %02d h', daysValue, hoursValue);
            elseif hoursValue > 0
                textValue = sprintf('%d h %02d min', hoursValue, minutesValue);
            elseif minutesValue > 0
                textValue = sprintf('%d min %02d s', minutesValue, secondsValue);
            else
                textValue = sprintf('%d s', secondsValue);
            end
        end

        function handleLocalRunMonitorEvent(app, payload)
            if isempty(app) || ~isvalid(app) || ~isstruct(payload)
                return;
            end
            kind = lower(char(string(getField(app, payload, 'kind', ''))));
            switch kind
                case 'console'
                    appendMonitorConsole(app, getField(app, payload, 'text', ''));
                case 'progress'
                    app.MonitorHasModuleProgress = true;
                    data = getField(app, payload, 'data', struct());
                    value = getField(app, data, 'value', ...
                        getField(app, data, 'nodeProgress', app.MonitorLastProgress));
                    indeterminate = logical(getField(app, data, ...
                        'indeterminate', false));
                    if isequal(value, app.MonitorLastProgress)
                        where = getField(app, data, 'where', []);
                        maxEpochs = getField(app, data, 'max_epochs', []);
                        if isnumeric(where) && isscalar(where) && isnumeric(maxEpochs) && ...
                                isscalar(maxEpochs) && maxEpochs > 0
                            value = max(0, min(1, double(where) / double(maxEpochs)));
                        end
                    end
                    try
                        value = double(value);
                        if ~isscalar(value) || ~isfinite(value)
                            value = app.MonitorLastProgress;
                        end
                    catch
                        value = app.MonitorLastProgress;
                    end
                    if indeterminate
                        value = app.MonitorLastProgress;
                    else
                        value = max(app.MonitorLastProgress, value);
                    end
                    message = getField(app, data, 'message', ...
                        getField(app, data, 'last_train_line', ...
                        getField(app, data, 'status', 'Local worker running...')));
                    appendMonitorProgressEvent(app, data, message);
                    eta = getField(app, data, 'eta', '');
                    updateRunMonitorProgress(app, value, message, eta);
            end
        end

        function appendMonitorProgressEvent(app, data, message)
            message = strtrim(char(string(message)));
            if isempty(message)
                return;
            end
            scope = strtrim(char(string(getField(app, data, 'scope', 'progress'))));
            if isempty(scope)
                scope = 'progress';
            end
            current = getField(app, data, 'current', []);
            total = getField(app, data, 'total', []);
            counter = '';
            if isnumeric(current) && isscalar(current) && isfinite(current) && ...
                    isnumeric(total) && isscalar(total) && isfinite(total) && total > 0
                counter = sprintf(' %g/%g', double(current), double(total));
            end
            signature = sprintf('%s|%s|%s', scope, counter, message);
            if strcmp(signature, app.MonitorLastProgressConsoleSignature)
                return;
            end
            stamp = char(datetime('now', 'Format', 'HH:mm:ss'));
            appendMonitorConsole(app, sprintf('[%s] [%s%s] %s', ...
                stamp, scope, counter, message));
            app.MonitorLastProgressConsoleSignature = signature;
        end

        function updateRunMonitorFromHubJob(app, job)
            statusText = lower(char(string(getField(app, job, 'status', 'unknown'))));
            progressValue = hubJobProgressValue(app, job);
            progressMessage = ['Hub: ' statusText];
            shouldUpdateProgress = true;
            if isempty(progressValue)
                switch statusText
                    case {'submitted','queued'}
                        progressValue = app.MonitorLastProgress;
                    case 'running'
                        progressValue = app.MonitorLastProgress;
                        if app.MonitorHasModuleProgress
                            shouldUpdateProgress = false;
                        else
                            progressMessage = ...
                                'Hub: running (waiting for module progress)';
                        end
                    case 'cancelling'
                        progressValue = max(app.MonitorLastProgress, 0.10);
                    case 'done'
                        progressValue = 1;
                    otherwise
                        progressValue = app.MonitorLastProgress;
                end
            else
                progressValue = max(app.MonitorLastProgress, progressValue);
            end
            if shouldUpdateProgress
                updateRunMonitorProgress(app, progressValue, progressMessage);
            end

            detail = hubJobConsoleText(app, job);
            signature = [statusText newline detail];
            if ~strcmp(signature, app.MonitorLastHubConsoleText)
                stamp = char(datetime('now', 'Format', 'HH:mm:ss'));
                appendMonitorConsole(app, sprintf('[%s] Hub status: %s', stamp, statusText));
                if ~isempty(strtrim(detail))
                    appendMonitorConsole(app, detail);
                end
                app.MonitorLastHubConsoleText = signature;
            end
        end

        function value = hubJobProgressValue(app, job)
            value = [];
            candidates = { ...
                getField(app, job, 'progress', []), ...
                getField(app, job, 'progress_value', []), ...
                getField(app, job, 'progressValue', [])};
            result = getField(app, job, 'result_json', struct());
            if isstruct(result)
                candidates{end+1} = getField(app, result, 'progress', []); %#ok<AGROW>
            end
            for i = 1:numel(candidates)
                candidate = candidates{i};
                if isstruct(candidate)
                    candidate = getField(app, candidate, 'value', []);
                end
                if isnumeric(candidate) && isscalar(candidate) && isfinite(candidate)
                    value = double(candidate);
                    if value > 1 && value <= 100
                        value = value / 100;
                    end
                    value = max(0, min(1, value));
                    return;
                end
            end
        end

        function text = hubJobConsoleText(app, job)
            lines = {};
            fields = {'message','error','error_message','detail','stdout','stderr','log','worker_log','console'};
            for i = 1:numel(fields)
                value = getField(app, job, fields{i}, '');
                if (ischar(value) || isstring(value)) && ~isempty(strtrim(char(string(value))))
                    lines{end+1} = sprintf('[%s] %s', fields{i}, char(string(value))); %#ok<AGROW>
                end
            end
            result = getField(app, job, 'result_json', struct());
            if isstruct(result)
                for i = 1:numel(fields)
                    value = getField(app, result, fields{i}, '');
                    if (ischar(value) || isstring(value)) && ~isempty(strtrim(char(string(value))))
                        lines{end+1} = sprintf('[result.%s] %s', fields{i}, char(string(value))); %#ok<AGROW>
                    end
                end
            end
            text = strjoin(lines, newline);
        end

        function handleLocalProcessRunFinished(app, result, future, completion) %#ok<INUSD>
            if isempty(app) || ~isvalid(app)
                return;
            end
            app.LocalRunSubmission = struct();

            statusText = lower(char(string(getField(app, result, 'status', 'failed'))));
            runJsonPath = char(string(getField(app, result, 'run_json_path', '')));
            if isempty(runJsonPath) && isfield(completion, 'runPath')
                runJsonPath = fullfile(char(string(completion.runPath)), 'run.json');
            end
            loadedRun = [];
            if ~isempty(runJsonPath) && exist(runJsonPath, 'file') == 2
                try
                    [loadedRun, ~] = pipelineRunLoad(runJsonPath);
                catch
                    loadedRun = [];
                end
            end

            projectMatPath = char(string(getField(app, result, 'project_mat_path', '')));
            if isempty(projectMatPath) && isfield(completion, 'projectPath')
                projectMatPath = char(string(completion.projectPath));
            end
            previousProject = app.CurrentProject;
            reloadedProject = [];
            if ~runtimeStartsFromClassifier(app) && ~isempty(projectMatPath)
                try
                    [reloadedProject, loadMsg] = shallowLoad(projectMatPath);
                    if isempty(reloadedProject)
                        error('pipeline2:LocalProjectReloadFailed', '%s', loadMsg);
                    end
                    app.CurrentProject = reloadedProject;
                    if ~isempty(app.CurrentProjectVarName)
                        assignin('base', char(string(app.CurrentProjectVarName)), reloadedProject);
                    end
                catch ME
                    appendRunReport(app, ['Local project reload failed: ' ME.message], struct());
                    reloadedProject = [];
                end
            end

            if ~isempty(loadedRun) && isa(loadedRun, 'pipelineRun')
                app.CurrentRun = loadedRun;
                app.CurrentRunPath = loadedRun.path;
                if ~isempty(reloadedProject) && isa(reloadedProject, 'shallow')
                    try
                        ids = arrayfun(@(r)char(string(r.runId)), ...
                            reloadedProject.processing.pipelineRun, 'UniformOutput', false);
                        idx = find(strcmp(ids, char(string(loadedRun.runId))), 1);
                        if ~isempty(idx)
                            app.CurrentRun = reloadedProject.processing.pipelineRun(idx);
                            app.CurrentRunPath = app.CurrentRun.path;
                        end
                    catch
                    end
                end
            end

            mutationManifest = runMutationManifest(app, result, loadedRun);
            refreshReport = refreshRunMutationHandles(app, mutationManifest, ...
                previousProject, reloadedProject, false);
            emitLocalProcessRunWorkspaceRefresh(app, result, reloadedProject, ...
                projectMatPath, mutationManifest, refreshReport);
            clearRuntimeDataSeriesCache(app);
            try, updateRuntimeResourceInventory(app); catch, end
            stopActiveRunControl(app, terminalButtonText(app, statusText));

            switch statusText
                case 'done'
                    updateRunMonitorProgress(app, 1, 'Local worker completed.');
                    appendMonitorConsole(app, '[result] Local pipeline run completed successfully.');
                    setRuntimeStatus(app, ['Local process run done: ' runJsonPath]);
                    appendRunReport(app, 'Local process run: OK', getField(app, result, 'summary', struct()));
                    showRunCompletedMessage(app);
                case 'cancelled'
                    updateRunMonitorProgress(app, app.MonitorLastProgress, 'Local worker cancelled.');
                    appendMonitorConsole(app, '[result] Local pipeline run cancelled.');
                    setRuntimeStatus(app, ['Local process run cancelled: ' runJsonPath]);
                    appendRunReport(app, 'Local process run: cancelled', result);
                    uialert(app.UIFigure, ...
                        'The local process run stopped at a safe cancellation point.', ...
                        'Run cancelled', 'Icon', 'warning');
                otherwise
                    failureKind = char(string(getField(app, result, 'failure_kind', '')));
                    if strcmpi(failureKind, 'no_rois')
                        message = ['Aucune ROI n’a été détectée. Le pipeline s’est arrêté à l’étape ' ...
                            'd’identification des ROI; les étapes suivantes n’ont pas été exécutées.' ...
                            newline newline ...
                            'Vérifiez le motif enregistré, l’image et le canal de référence, ainsi que le seuil.'];
                        updateRunMonitorProgress(app, app.MonitorLastProgress, 'Aucune ROI détectée.');
                        appendMonitorConsole(app, ['[result] ' strrep(message, newline, ' ')]);
                        setRuntimeStatus(app, ['Pipeline arrêté : aucune ROI détectée (' runJsonPath ')']);
                        appendRunReport(app, 'Local pipeline run: stopped (no ROIs detected)', ...
                            getField(app, result, 'summary', struct()));
                        uialert(app.UIFigure, message, 'Aucune ROI détectée', 'Icon', 'info');
                        return;
                    end
                    errorText = char(string(getField(app, result, 'error', 'Local process run failed.')));
                    if isempty(strtrim(errorText))
                        errorText = 'Local process run failed.';
                    end
                    updateRunMonitorProgress(app, app.MonitorLastProgress, 'Local worker failed.');
                    appendMonitorConsole(app, ['[result] ' errorText]);
                    setRuntimeStatus(app, ['Local process run failed: ' runJsonPath]);
                    appendRunReport(app, 'Local process run: failed', result);
                    uialert(app.UIFigure, compactLocalProcessError(app, errorText), ...
                        'Run failed', 'Icon', 'error');
            end
        end

        function emitLocalProcessRunWorkspaceRefresh(app, result, projectObj, projectMatPath, mutationManifest, refreshReport)
            if exist('detecdiv_event', 'file') ~= 2
                return;
            end
            payload = struct();
            payload.kind = 'pipelineRun';
            payload.action = 'completed';
            payload.source = 'pipeline2_local_process';
            payload.status = char(string(getField(app, result, 'status', 'failed')));
            payload.runId = char(string(getField(app, result, 'run_id', '')));
            payload.projectObj = projectObj;
            payload.projectVarName = char(string(app.CurrentProjectVarName));
            payload.projectMatPath = char(string(projectMatPath));
            payload.projectPath = regexprep(payload.projectMatPath, '\.mat$', '', 'ignorecase');
            [~, payload.projectName] = fileparts(payload.projectPath);
            payload.summary = getField(app, result, 'summary', struct());
            payload.mutationManifest = mutationManifest;
            payload.refreshReport = refreshReport;
            try
                detecdiv_event('emit', 'pipelineRunCompleted', payload);
                detecdiv_event('emit', 'workspaceChanged', payload);
            catch
            end
        end

        function manifest = runMutationManifest(app, source, runObj)
            manifest = struct();
            try
                manifest = getField(app, source, 'mutation_manifest', struct());
            catch
                manifest = struct();
            end
            if isstruct(manifest) && ~isempty(fieldnames(manifest))
                return;
            end
            try
                resultJson = getField(app, source, 'result_json', struct());
                if isstruct(resultJson)
                    manifest = getField(app, resultJson, 'mutation_manifest', struct());
                end
            catch
                manifest = struct();
            end
            if isstruct(manifest) && ~isempty(fieldnames(manifest))
                return;
            end
            try
                if ~isempty(runObj) && isa(runObj, 'pipelineRun') && ...
                        isstruct(runObj.outputs) && isfield(runObj.outputs, 'mutationManifest')
                    manifest = runObj.outputs.mutationManifest;
                end
            catch
                manifest = struct();
            end
        end

        function report = refreshRunMutationHandles(app, manifest, oldProject, newProject, fromHub, progressCallback)
            if nargin < 6
                progressCallback = [];
            end
            report = struct('matchedRois', 0, 'refreshedRois', 0, ...
                'refreshedChannels', 0, 'refreshedDataRois', 0, ...
                'skippedUnloadedRois', 0, ...
                'pendingRois', {{}}, 'warnings', {{}});
            if ~isstruct(manifest) || isempty(fieldnames(manifest)) || ...
                    exist('detecdiv_refresh_run_mutations', 'file') ~= 2
                return;
            end
            retryCount = 1;
            retryPause = 0;
            if fromHub
                retryCount = 8;
                retryPause = 0.5;
            end
            explicitRois = app.ExplicitRuntimeRoiList;
            classiObj = app.RuntimeClassifierSource;
            callback = @(progress)reportHubProjectRefreshProgress(app, progressCallback, ...
                0.96 + 0.03 * double(getField(app, progress, 'fraction', 0)), ...
                char(string(getField(app, progress, 'message', 'Refreshing ROI outputs...'))), ...
                char(string(getField(app, progress, 'stage', 'roiOutputRefresh'))));
            try
                report = detecdiv_refresh_run_mutations(manifest, ...
                    'Classifier', classiObj, 'Project', oldProject, ...
                    'RoiList', explicitRois, 'RetryCount', retryCount, ...
                    'RetryPause', retryPause, 'ProgressCallback', callback);
            catch ME
                report.warnings{end+1} = ME.message;
            end
            if ~isempty(newProject) && isa(newProject, 'shallow') && ...
                    (~isequal(oldProject, newProject))
                try
                    second = detecdiv_refresh_run_mutations(manifest, ...
                        'Project', newProject, 'RetryCount', 1, 'RetryPause', 0, ...
                        'ProgressCallback', callback);
                    report = mergeMutationRefreshReports(app, report, second);
                catch ME
                    report.warnings{end+1} = ME.message;
                end
            end
            if report.refreshedRois > 0
                appendRunReport(app, sprintf( ...
                    'Refreshed %d loaded ROI(s), %d output channel(s).', ...
                    report.refreshedRois, report.refreshedChannels), report);
            end
            if ~isempty(report.pendingRois)
                appendRunReport(app, sprintf( ...
                    'Output synchronization is still pending for ROI(s): %s', ...
                    strjoin(report.pendingRois, ', ')), report);
            end
            if report.skippedUnloadedRois > 0
                appendRunReport(app, sprintf( ...
                    'Left %d unloaded ROI output(s) on disk; they will load when opened.', ...
                    report.skippedUnloadedRois), report);
            end
        end

        function out = mergeMutationRefreshReports(app, out, incoming) %#ok<INUSD>
            countFields = {'matchedRois','refreshedRois','refreshedChannels', ...
                'refreshedDataRois','skippedUnloadedRois'};
            for i = 1:numel(countFields)
                name = countFields{i};
                out.(name) = double(getField(app, out, name, 0)) + ...
                    double(getField(app, incoming, name, 0));
            end
            out.pendingRois = unique([getField(app, out, 'pendingRois', {}) ...
                getField(app, incoming, 'pendingRois', {})], 'stable');
            out.warnings = [getField(app, out, 'warnings', {}) ...
                getField(app, incoming, 'warnings', {})];
        end

        function text = compactLocalProcessError(app, text) %#ok<INUSD>
            text = char(string(text));
            text = regexprep(text, '\s+', ' ');
            if strlength(string(text)) > 600
                text = [char(extractBefore(string(text), 601)) '...'];
            end
        end

        function pollHubRunStatus(app, showErrors)
            if nargin < 2
                showErrors = false;
            end
            queueHubRunStatusPoll(app, showErrors);
        end

        function queueHubRunStatusPoll(app, showErrors)
            if nargin < 2
                showErrors = false;
            end
            jobId = currentHubRunJobId(app);
            if isempty(jobId)
                stopActiveRunControl(app, 'Run !');
                return;
            end
            if ~isempty(app.HubRunMonitorToken)
                return;
            end
            if isempty(app.HubRunMonitorJobId)
                app.HubRunMonitorJobId = jobId;
            end
            try
                startHubRunStatusWorker(app, jobId, showErrors, 1);
            catch ME
                handleHubRunStatusError(app, ME, showErrors);
            end
        end

        function startHubRunStatusWorker(app, jobId, showErrors, maxIterations, hubOverride)
            if nargin >= 5 && isstruct(hubOverride)
                hub = hubOverride;
            else
                hub = hubSettingsFromUi(app);
            end
            hub.timeout = min(5, double(hub.timeout));
            keepMonitoring = isinf(maxIterations);
            config = struct('hub', hub, 'jobId', jobId, ...
                'periodSeconds', 3, 'maxIterations', maxIterations);
            app.HubRunMonitorToken = detecdiv_hub_broker('register', ...
                'run_status', config, ...
                struct('owner', app, 'method', 'handleHubRunStatusPayload', ...
                    'args', {{jobId, showErrors, keepMonitoring}}));
        end

        function applyHubRunStatus(app, job, jobId)
            updateCurrentRunFromHubJob(app, job);
            statusText = char(string(getField(app, job, 'status', 'unknown')));
            setRuntimeStatus(app, formatHubRunStatusText(app, job, app.CurrentRun));
            updateRunMonitorFromHubJob(app, job);
            if app.ActiveRunCancelRequested && any(strcmpi(statusText, {'queued','running'}))
                app.CancelrunButton.Text = 'Cancelling...';
            elseif any(strcmpi(statusText, {'queued','running','cancelling'}))
                app.CancelrunButton.Text = 'Cancel run...';
                app.CancelrunButton.Enable = 'on';
            end
            if ~any(strcmpi(statusText, {'done','failed','cancelled'}))
                return;
            end

            terminalStatus = statusText;
            stopActiveRunControl(app, terminalButtonText(app, terminalStatus));
            setRuntimeStatus(app, formatHubRunStatusText(app, job, app.CurrentRun));
            appendRunReport(app, ['Hub run finished: ' terminalStatus], job);
            resumeDialog = uiprogressdlg(app.UIFigure, ...
                'Title', 'Refreshing Hub project', ...
                'Message', 'Preparing local project refresh...', ...
                'Value', 0);
            ddProgressGuard9 = onCleanup(@()detecdiv_close_progress(resumeDialog)); %#ok<NASGU>
            drawnow nocallbacks;
            drawnow;
            progressCallback = @(progress)updateHubProjectRefreshProgress(app, resumeDialog, progress);
            emitLocalWorkspaceRefreshForHubRun(app, job, progressCallback);
            try
                close(resumeDialog);
            catch
            end
            if strcmpi(terminalStatus, 'done')
                showRunCompletedMessage(app);
            else
                uialert(app.UIFigure, sprintf('Hub run %s finished with status: %s', jobId, terminalStatus), ...
                    'Hub run finished', 'Icon', terminalAlertIcon(app, terminalStatus));
            end
        end

        function handleHubRunStatusError(app, ME, showErrors)
            msg = compactHubStatusError(app, ME);
            if isTransientHubStatusError(app, ME)
                if showErrors
                    uialert(app.UIFigure, msg, 'Hub status temporarily unavailable', 'Icon', 'warning');
                else
                    setRuntimeStatus(app, msg);
                end
                return;
            end
            if showErrors
                uialert(app.UIFigure, msg, 'Hub status failed', 'Icon', 'error');
            else
                setRuntimeStatus(app, ['Hub status refresh failed: ' msg]);
            end
        end

        function updateHubProjectRefreshProgress(app, progressDialog, progress)
            if ~isstruct(progress)
                return;
            end
            try
                if ~isvalid(progressDialog)
                    return;
                end
                fraction = double(getField(app, progress, 'fraction', 0));
                if ~isscalar(fraction) || ~isfinite(fraction)
                    fraction = 0;
                end
                fraction = max(0, min(1, fraction));
                fraction = max(fraction, double(progressDialog.Value));
                message = char(string(getField(app, progress, 'message', ...
                    getField(app, progress, 'stage', 'Refreshing project...'))));
                progressDialog.Value = fraction;
                progressDialog.Message = sprintf('%3.0f%% | %s', 100 * fraction, message);
                drawnow limitrate;
            catch
            end
        end

        function reportHubProjectRefreshProgress(app, callback, fraction, message, stage)
            if isempty(callback)
                return;
            end
            progress = struct('fraction', fraction, 'message', message, 'stage', stage);
            try
                callback(progress);
            catch
                % UI progress reporting must not interrupt workspace refresh.
            end
        end

        function emitLocalWorkspaceRefreshForHubRun(app, job, progressCallback)
            if nargin < 3
                progressCallback = [];
            end
            reportHubProjectRefreshProgress(app, progressCallback, 0.01, ...
                'Resolving the client-side project path...', 'projectPath');
            payload = struct();
            payload.kind = 'pipelineRun';
            payload.action = 'completed';
            payload.source = 'pipeline2_hub';
            payload.status = char(string(getField(app, job, 'status', 'done')));
            payload.projectObj = [];
            payload.projectMatPath = '';
            payload.projectPath = '';
            payload.projectName = '';
            payload.projectVarName = '';
            payload.runId = currentHubRunJobId(app);
            payload.summary = struct();
            payload.mutationManifest = runMutationManifest(app, job, app.CurrentRun);
            payload.refreshReport = struct();

            previousProject = app.CurrentProject;

            if ~isempty(app.CurrentProjectVarName)
                payload.projectVarName = char(string(app.CurrentProjectVarName));
            end
            if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow')
                try
                    [pth, file] = app.CurrentProject.getPath;
                    payload.projectMatPath = fullfile(pth, [file '.mat']);
                    payload.projectPath = fullfile(pth, file);
                    payload.projectName = char(string(file));
                catch
                end
            end
            if isempty(payload.projectMatPath)
                try
                    projectPath = strtrim(getRuntimeValue(app, 'projectPath'));
                    if ~isempty(projectPath)
                        payload.projectMatPath = projectPath;
                        if endsWith(lower(projectPath), '.mat')
                            payload.projectPath = erase(projectPath, '.mat');
                            [~, nm] = fileparts(payload.projectPath);
                        else
                            payload.projectPath = projectPath;
                            [~, nm] = fileparts(projectPath);
                        end
                        payload.projectName = char(string(nm));
                    end
                catch
                end
            end
            reportHubProjectRefreshProgress(app, progressCallback, 0.03, ...
                ['Project path resolved: ' payload.projectMatPath], 'projectPath');
            try
                if ~isempty(app.CurrentProject) && isa(app.CurrentProject, 'shallow') && ...
                        ~isempty(payload.projectMatPath)
                    [projectObj, access, resumeInfo] = detecdiv_hub_resume_project_editing( ...
                        app.CurrentProject, 'Hub', hubSettingsFromUi(app), ...
                        'ProjectMatPath', payload.projectMatPath, ...
                        'ProgressCallback', progressCallback);
                    app.CurrentProject = projectObj;
                    payload.projectObj = projectObj;
                    payload.summary.hubAccess = access;
                    payload.summary.resumeMessage = resumeInfo.message;
                    if ~isempty(payload.projectVarName)
                        assignin('base', payload.projectVarName, projectObj);
                    end
                    appendRunReport(app, resumeInfo.message, struct());
                end
            catch ME
                payload.summary.resumeError = ME.message;
                appendRunReport(app, ['Local project refresh after Hub run failed: ' ME.message], struct());
                reportHubProjectRefreshProgress(app, progressCallback, 0.82, ...
                    ['Project reload failed: ' ME.message], 'projectReloadFailed');
            end
            reportHubProjectRefreshProgress(app, progressCallback, 0.96, ...
                'Refreshing already-open ROI results produced by this run...', 'roiOutputRefresh');
            payload.refreshReport = refreshRunMutationHandles(app, ...
                payload.mutationManifest, previousProject, payload.projectObj, true, progressCallback);
            reportHubProjectRefreshProgress(app, progressCallback, 0.99, ...
                'Notifying open Score windows to refresh outputs for loaded ROI only...', ...
                'pipelineRunNotification');
            try
                detecdiv_event('emit', 'pipelineRunCompleted', payload);
            catch
            end
            reportHubProjectRefreshProgress(app, progressCallback, 0.995, ...
                'Updating the main DetecDiv project browser...', 'workspaceNotification');
            try
                detecdiv_event('emit', 'workspaceChanged', payload);
            catch
            end
            reportHubProjectRefreshProgress(app, progressCallback, 1, ...
                'Project refresh finished.', 'complete');
        end

        function tf = isTransientHubStatusError(app, ME) %#ok<INUSD>
            msg = lower(char(string(ME.message)));
            tf = contains(msg, '502') || contains(msg, 'bad gateway') || ...
                contains(msg, '503') || contains(msg, '504') || ...
                contains(msg, 'gateway timeout') || ...
                contains(msg, 'temporarily unavailable');
        end

        function msg = compactHubStatusError(app, ME) %#ok<INUSD>
            raw = char(string(ME.message));
            lowerRaw = lower(raw);
            if contains(lowerRaw, '<html')
                if contains(lowerRaw, '502') || contains(lowerRaw, 'bad gateway')
                    msg = 'Hub status temporarily unavailable (502 Bad Gateway). The run monitor will retry.';
                    return;
                end
                raw = regexprep(raw, '<[^>]*>', ' ');
            end
            raw = regexprep(raw, '\s+', ' ');
            maxLen = 240;
            if strlength(string(raw)) > maxLen
                raw = char(extractBefore(string(raw), maxLen + 1));
                raw = [raw '...'];
            end
            msg = raw;
        end

        function updateCurrentRunFromHubJob(app, job)
            if isempty(app.CurrentRun) || ~isa(app.CurrentRun, 'pipelineRun') || ~isstruct(job)
                return;
            end
            try
                previousJobId = '';
                previousStatus = '';
                if ~isstruct(app.CurrentRun.ctx)
                    app.CurrentRun.ctx = struct();
                end
                if ~isfield(app.CurrentRun.ctx, 'hub') || ~isstruct(app.CurrentRun.ctx.hub)
                    app.CurrentRun.ctx.hub = struct();
                else
                    previousJobId = char(string(getField(app, app.CurrentRun.ctx.hub, 'job_id', '')));
                    previousStatus = char(string(getField(app, app.CurrentRun.ctx.hub, 'status', '')));
                end
                nextJobId = char(string(getField(app, job, 'id', currentHubRunJobId(app))));
                nextStatus = char(string(getField(app, job, 'status', 'unknown')));
                app.CurrentRun.ctx.hub.job_id = nextJobId;
                app.CurrentRun.ctx.hub.status = nextStatus;
                app.CurrentRun.ctx.hub.refreshed_at = char(datetime('now'));
                app.CurrentRun.status = ['hub_' app.CurrentRun.ctx.hub.status];
                app.HubRunMonitorLastStatus = nextStatus;
                applyHubRunUiLock(app, any(strcmpi(nextStatus, {'queued','running','cancelling'})));
                try
                    if ~strcmp(previousJobId, nextJobId) || ~strcmp(previousStatus, nextStatus)
                        pipelineRunSave(app.CurrentRun, struct('verbose', false));
                    end
                catch
                end
            catch
            end
        end

        function txt = formatHubRunStatusText(app, job, runObj)
            jobId = hubJobIdFromRunOrJob(app, runObj, job);
            statusText = char(string(getField(app, job, 'status', 'unknown')));
            runPath = '';
            try
                if ~isempty(runObj) && isa(runObj, 'pipelineRun')
                    runPath = fullfile(runObj.path, 'run.json');
                end
            catch
                runPath = '';
            end
            detail = hubJobProgressDetail(app, job);
            parts = {sprintf('Hub run %s: %s', jobId, statusText)};
            if ~isempty(detail)
                parts{end+1} = detail; %#ok<AGROW>
            end
            parts{end+1} = ['Last refresh: ' char(datetime('now', 'Format', 'HH:mm:ss'))]; %#ok<AGROW>
            if ~isempty(runPath)
                parts{end+1} = runPath; %#ok<AGROW>
            end
            txt = strjoin(parts, ' | ');
        end

        function detail = hubJobProgressDetail(app, job) %#ok<INUSD>
            detail = '';
            try
                result = getField(app, job, 'result_json', struct());
                if ~isstruct(result) || ~isfield(result, 'progress') || ~isstruct(result.progress)
                    return;
                end
                progress = result.progress;
                currentStep = char(string(getField(app, progress, 'current_step', '')));
                lastMessage = char(string(getField(app, progress, 'last_message', '')));
                if ~isempty(currentStep)
                    detail = ['Step: ' currentStep];
                end
                if ~isempty(lastMessage) && ~strcmp(lastMessage, currentStep)
                    if isempty(detail)
                        detail = lastMessage;
                    else
                        detail = [detail ' - ' lastMessage];
                    end
                end
            catch
                detail = '';
            end
        end

        function jobId = currentHubRunJobId(app)
            jobId = app.HubRunMonitorJobId;
            if ~isempty(jobId)
                return;
            end
            jobId = hubJobIdFromRunOrJob(app, app.CurrentRun, struct());
        end

        function jobId = hubJobIdFromRunOrJob(app, runObj, job) %#ok<INUSD>
            jobId = '';
            try
                if isstruct(job) && isfield(job, 'id') && ~isempty(job.id)
                    jobId = char(string(job.id));
                    return;
                end
            catch
            end
            try
                if ~isempty(runObj) && isa(runObj, 'pipelineRun') && isstruct(runObj.ctx) && ...
                        isfield(runObj.ctx, 'hub') && isstruct(runObj.ctx.hub)
                    if isfield(runObj.ctx.hub, 'job_id') && ~isempty(runObj.ctx.hub.job_id)
                        jobId = char(string(runObj.ctx.hub.job_id));
                    elseif isfield(runObj.ctx.hub, 'hub_job_id') && ~isempty(runObj.ctx.hub.hub_job_id)
                        jobId = char(string(runObj.ctx.hub.hub_job_id));
                    end
                end
            catch
                jobId = '';
            end
        end

        function text = terminalButtonText(app, statusText) %#ok<INUSD>
            if strcmpi(statusText, 'cancelled')
                text = 'Resume run';
            else
                text = 'Run !';
            end
        end

        function icon = terminalAlertIcon(app, statusText) %#ok<INUSD>
            if strcmpi(statusText, 'done')
                icon = 'success';
            elseif strcmpi(statusText, 'cancelled')
                icon = 'warning';
            else
                icon = 'error';
            end
        end

        function showRunCompletedMessage(app)
            try
                uialert(app.UIFigure, 'Run completed sucessufully', 'Run completed', 'Icon', 'success');
            catch
            end
        end

        function loadRunIntoUi(app, runObj, refreshUi)
            if nargin < 3 || isempty(refreshUi)
                refreshUi = true;
            end
            if isempty(runObj) || ~isa(runObj, 'pipelineRun')
                return;
            end
            app.RuntimeInventoryMetadataOnly = true;
            app.SavedRuntimeInventoryContext = runObj.ctx;
            app.LastValidationReport = struct();
            app.LastValidationOk = false;
            clearRuntimeDataSeriesCache(app);
            beginModuleTabRefreshBatch(app);
            tabBatchCleanup = onCleanup(@()endModuleTabRefreshBatch(app, logical(refreshUi))); %#ok<NASGU>
            wasSuspended = app.RuntimeInventoryRefreshSuspended;
            app.RuntimeInventoryRefreshSuspended = true;
            cleanupObj = onCleanup(@()setRuntimeInventoryRefreshSuspended(app, wasSuspended)); %#ok<NASGU>
            app.CurrentRun = runObj;
            app.CurrentRunPath = runObj.path;
            setRuntimeModeUnlocked(app, true);
            try
                app.TemplateidEditField.Value = char(string(runObj.runId));
                app.RuntimeValues.runId = char(string(runObj.runId));
            catch
            end
            ctx = runObj.ctx;
            app.Data.nodes = pipelineRestoreRoiPatternsFromRun(app.Data.nodes, ctx);
            [restoredNodes, restoredLinks] = ...
                pipelineRestoreClassifierLinksFromRun(app.Data.nodes,ctx);
            if ~isempty(restoredLinks)
                app.Data.nodes = restoredNodes;
            end
            if isstruct(ctx)
                if isfield(ctx, 'run') && isstruct(ctx.run)
                    if isfield(ctx.run, 'runPolicy') && strcmpi(char(string(ctx.run.runPolicy)), 'restart')
                        app.ResumeoptionsDropDown.Value = 'Restart from scratch';
                    else
                        app.ResumeoptionsDropDown.Value = 'Resume previous progress';
                    end
                    if isfield(ctx.run, 'gpuPolicy') && ~isempty(ctx.run.gpuPolicy)
                        gpuPolicy = char(string(ctx.run.gpuPolicy));
                        if strcmpi(gpuPolicy, 'module_default')
                            gpuPolicy = 'auto';
                        end
                        if any(strcmp(app.ExecutionDropDown.ItemsData, gpuPolicy))
                            app.ExecutionDropDown.Value = gpuPolicy;
                        elseif any(strcmp(app.ExecutionDropDown.Items, gpuPolicy))
                            app.ExecutionDropDown.Value = gpuPolicy;
                        end
                    end
                    if isfield(ctx.run, 'nodeParams') && isstruct(ctx.run.nodeParams)
                        app.RuntimeNodeParams = uiRuntimeNodeParamsFromRun(app, ctx.run.nodeParams);
                    end
                    if isfield(ctx.run, 'selectedNodes')
                        applySelectedRunNodes(app, ctx.run.selectedNodes);
                    end
                    if isfield(ctx.run, 'projectPath')
                        setRuntimeValuePreserveParse(app, 'projectPath', ctx.run.projectPath);
                    end
                    inputMode = runtimeInputModeFromRunContext(app, ctx);
                    if ~isempty(inputMode)
                        setRuntimeValuePreserveParse(app, 'inputSourceMode', inputMode);
                    end
                    if isfield(ctx.run, 'intent') && ~isempty(ctx.run.intent)
                        setRuntimeValuePreserveParse(app, 'intent', normalizeStartupIntent(app, ctx.run.intent));
                    elseif isfield(ctx.run, 'classifierIntent') && ~isempty(ctx.run.classifierIntent)
                        setRuntimeValuePreserveParse(app, 'intent', normalizeStartupIntent(app, ctx.run.classifierIntent));
                    end
                    if isfield(ctx.run, 'executionTarget') && isstruct(app.HubFieldHandles) && ...
                            isfield(app.HubFieldHandles, 'executionTarget') && isvalid(app.HubFieldHandles.executionTarget)
                        target = char(string(ctx.run.executionTarget));
                        if any(strcmp(app.HubFieldHandles.executionTarget.ItemsData, target))
                            app.HubFieldHandles.executionTarget.Value = target;
                            app.RuntimeValues.executionTarget = target;
                        end
                    end
                end
                if isfield(ctx, 'hub') && isstruct(ctx.hub)
                    applyHubSettingsToUi(app, ctx.hub);
                    updateHubRuntimeControlsVisibility(app);
                end
                if isfield(ctx, 'sel') && isstruct(ctx.sel)
                    if strcmpi(runtimeInputModeFromRunContext(app, ctx), 'raw_dataloader') ...
                            && isfield(ctx.sel, 'sourceFovs') && ~isempty(ctx.sel.sourceFovs)
                        setRuntimeValuePreserveParse(app, 'fovs', selectionToText(app, ctx.sel.sourceFovs));
                    elseif isfield(ctx.sel, 'fovs')
                        setRuntimeValuePreserveParse(app, 'fovs', selectionToText(app, ctx.sel.fovs));
                    end
                    if isfield(ctx.sel, 'frames'), setRuntimeValuePreserveParse(app, 'frames', selectionToText(app, ctx.sel.frames)); end
                    if isfield(ctx.sel, 'rois'), setRuntimeValuePreserveParse(app, 'rois', selectionToText(app, ctx.sel.rois)); end
                end
                if isfield(ctx, 'io') && isstruct(ctx.io) && isfield(ctx.io, 'existingPolicy') && ~isempty(ctx.io.existingPolicy)
                    setRuntimeValuePreserveParse(app, 'outputPolicy', ctx.io.existingPolicy);
                end
            end
            if isempty(app.CurrentProject) && ~isempty(runObj.projectPath)
                projectPath = char(string(runObj.projectPath));
                localProjectPath = hubRemotePathToLocalPath(app, projectPath);
                if ~isempty(localProjectPath)
                    projectPath = localProjectPath;
                elseif ~isempty(runObj.path)
                    % A run folder is <project>/pipeline/<runId>; use it as
                    % a local, authoritative fallback when the saved Hub
                    % projectPath still points to /data or an unavailable
                    % drive mapping.
                    try
                        projectFolder = fileparts(fileparts(char(string(runObj.path))));
                        if isfolder(projectFolder)
                            projectPath = projectFolder;
                        end
                    catch
                    end
                end
                bindProjectFromPath(app, [regexprep(projectPath, '\.mat$', '') '.mat'], false);
            end
            rawDataPath = pipelineRunRawDataPath(ctx);
            if ~isempty(rawDataPath)
                localRawPath = hubRemotePathToLocalPath(app, rawDataPath);
                if ~isempty(localRawPath)
                    rawDataPath = localRawPath;
                end
                setRuntimeValuePreserveParse(app, 'rawDataPath', rawDataPath);
                app.RuntimeValues.rawDataPathActive = rawDataPath;
            end
            app.SavedRuntimeInventoryContext = ctx;
            app.RuntimeInventoryRefreshSuspended = wasSuspended;
            hydrateRuntimeInventoryFromRunContext(app, ctx);
            if ~wasSuspended
                updateRuntimeResourceInventory(app);
            end
            updateRuntimeInputStates(app);
            if logical(refreshUi)
                if ~isempty(restoredLinks)
                    refreshSelectedModuleTable(app, false);
                end
                refreshModuleTabs(app);
                refreshValidationReport(app);
            end
            markRunDirty(app, false);
            try
                runStatus = char(string(runObj.status));
                hubStatus = '';
                if isstruct(runObj.ctx) && isfield(runObj.ctx, 'hub') && isstruct(runObj.ctx.hub) && ...
                        isfield(runObj.ctx.hub, 'status') && ~isempty(runObj.ctx.hub.status)
                    hubStatus = char(string(runObj.ctx.hub.status));
                elseif startsWith(lower(runStatus), 'hub_')
                    hubStatus = extractAfter(runStatus, 4);
                    hubStatus = char(string(hubStatus));
                end
                if any(strcmpi(hubStatus, {'queued','running','cancelling'})) && ~isempty(hubJobIdFromRunOrJob(app, runObj, struct()))
                    startHubRunMonitor(app, runObj, struct('status', hubStatus));
                    setRuntimeStatus(app, formatHubRunStatusText(app, struct('status', hubStatus), runObj));
                elseif strcmpi(runStatus, 'cancelled') || strcmpi(hubStatus, 'cancelled')
                    app.RunButton.Text = 'Resume run';
                else
                    app.RunButton.Text = 'Run !';
                end
            catch
                app.RunButton.Text = 'Run !';
            end
        end

        function [job, runObj, alreadySubmitted] = submitHubRunWithLockResolution(app, runObj, hub)
            alreadySubmitted = false;
            submitArgs = {'hub', hub, ...
                'SaveProject', true, ...
                'ProjectResolveInitialWaitSec', 0, ...
                'ProjectResolveAttempts', 1, ...
                'ProjectResolveIntervalSec', 0.5};
            try
                [job, runObj] = detecdiv_hub_submit_pipeline_run( ...
                    runObj, app.CurrentProject, submitArgs{:});
                return;
            catch ME
                originalError = ME;
            end

            % The server may have accepted POST even if its response or the
            % following local save failed. Resolve that outcome before retry.
            try
                job = detecdiv_hub_find_active_pipeline_run(runObj, app.CurrentProject, hub);
            catch
                rethrow(originalError);
            end
            if ~isempty(job)
                runObj.ctx.hub.job_id = char(string(job.id));
                runObj.ctx.hub.status = char(string(job.status));
                runObj.status = ['hub_' char(string(job.status))];
                alreadySubmitted = true;
                return;
            end
            if ~isHubProjectLockedException(app, originalError)
                rethrow(originalError);
            end

            % The first submission attempt releases this MATLAB session's own
            % edit lease. Any remaining client lease therefore belongs to a
            % different/stale session and can be presented explicitly.
            summary = detecdiv_hub_project_lock_summary(app.CurrentProject, hub);
            if summary.lock_count == 0
                [job, runObj] = detecdiv_hub_submit_pipeline_run( ...
                    runObj, app.CurrentProject, submitArgs{:});
                return;
            end

            details = detecdiv_hub_format_project_lock_summary(summary);
            lockKinds = string({summary.locks.kind});
            if any(strcmpi(lockKinds, 'server_job'))
                error('detecdiv_hub_submit_pipeline_run:ProjectLocked', '%s', ...
                    [details newline newline ...
                    'A running Hub job owns this lock. Cancel it from Monitor or wait for it to finish before retrying.']);
            end
            if ~all(strcmpi(lockKinds, 'client_edit_lease'))
                rethrow(originalError);
            end

            prompt = [details newline newline ...
                'Release the listed client lease and submit this run?' newline ...
                'Only continue if that DetecDiv session is closed or no longer writing. ' ...
                'Forcing release while it is active can allow concurrent writes to the same project.'];
            choice = uiconfirm(app.UIFigure, prompt, 'Hub project is locked', ...
                'Options', {'Release lock and submit','Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');
            if ~strcmp(choice, 'Release lock and submit')
                rethrow(originalError);
            end

            for iLock = 1:numel(summary.locks)
                lock = summary.locks(iLock);
                if ~isempty(lock.id)
                    detecdiv_hub_release_project_lease(app.CurrentProject, lock.id, hub);
                end
            end

            remaining = detecdiv_hub_project_lock_summary(app.CurrentProject, hub);
            if remaining.lock_count > 0
                error('detecdiv_hub_submit_pipeline_run:ProjectLocked', '%s', ...
                    [detecdiv_hub_format_project_lock_summary(remaining) newline newline ...
                    'The Hub still reports an active lock after the release request.']);
            end

            [job, runObj] = detecdiv_hub_submit_pipeline_run( ...
                runObj, app.CurrentProject, submitArgs{:});
        end

        function hydrateRuntimeInventoryFromRunContext(app, ctx)
            if ~isstruct(ctx)
                return;
            end

            savedSelections = struct( ...
                'fovs', getRuntimeValue(app, 'fovs'), ...
                'frames', getRuntimeValue(app, 'frames'), ...
                'rois', getRuntimeValue(app, 'rois'));
            cleanupObj = onCleanup(@()restoreRuntimeSelectionText(app, savedSelections)); %#ok<NASGU>

            info = runtimeInventoryFromRunContext(app, ctx);
            if isstruct(info) && isfield(info, 'ok') && logical(info.ok)
                app.RuntimeParseInfo = info;
                applyRuntimeParseInfo(app, info);
            end

            % Opening a saved run never reparses the remote raw dataset.
            % Its snapshot (or the loaded project) supplies the initial UI.
            if app.RuntimeInventoryMetadataOnly
                return;
            end

            mode = runtimeInputModeFromRunContext(app, ctx);
            if isempty(mode)
                mode = getRuntimeValue(app, 'inputSourceMode');
            end
            if ~strcmpi(mode, 'raw_dataloader')
                return;
            end

            rawDataPath = strtrim(getRuntimeValue(app, 'rawDataPath'));
            if isempty(rawDataPath)
                rawDataPath = pipelineRunRawDataPath(ctx);
            end
            if isempty(rawDataPath) || ~(exist(rawDataPath, 'dir') == 7 || exist(rawDataPath, 'file') == 2)
                return;
            end

            if ~rawParserIsCurrent(app, rawDataPath)
                parseRuntimeRawDataPath(app, rawDataPath);
            end
        end

        function restoreRuntimeSelectionText(app, savedSelections)
            if ~isstruct(savedSelections)
                return;
            end
            keys = {'fovs','frames','rois'};
            for i = 1:numel(keys)
                key = keys{i};
                if isfield(savedSelections, key)
                    value = strtrim(char(string(savedSelections.(key))));
                    if ~isempty(value)
                        setRuntimeValuePreserveParse(app, key, value);
                    end
                end
            end
        end
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