classdef pipelineGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RefreshlibraryButton           matlab.ui.control.Button
        DuplicatefromlibraryButton     matlab.ui.control.Button
        UIModuleLibraryTable           matlab.ui.control.Table
        ModulelibraryLabel             matlab.ui.control.Label
        ConnectDisconnectmodulesButton  matlab.ui.control.Button
        UIModuleParametersTable         matlab.ui.control.Table
        OpenselectedmoduleButton        matlab.ui.control.Button
        CreaterunButton                 matlab.ui.control.Button
        CheckpipelineButton             matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        RunpipelineButton               matlab.ui.control.Button
        UIModuleListTable               matlab.ui.control.Table
        AddmoduleButton                 matlab.ui.control.Button
        ModuletypeDropDown              matlab.ui.control.DropDown
        ModuletypeDropDownLabel         matlab.ui.control.Label
        UIModulesAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Data struct = struct('nodes',[],'edges',[])
        ModuleHandles = gobjects(0)
        ModuleTextHandles = gobjects(0)
        ModuleBadgeHandles = gobjects(0)
        ModuleMarkers = gobjects(0)
        EdgeHandles = gobjects(0)
        EdgeLabelHandles = gobjects(0)
        InPortHandles cell = {}
        OutPortHandles cell = {}
        InPortLabelHandles cell = {}
        OutPortLabelHandles cell = {}
        SelectedPorts struct = struct('moduleIdx',{},'isOut',{},'portName',{})
        SelectedModules double = []
        PendingAddModule logical = false
        PendingTemplate struct = struct()
        DraggingModule double = NaN
        DragOffset double = [0 0]
        ModuleIdCounter double = 0
        LibraryEntries struct = struct('name',{},'type',{},'pkg',{},'source',{},'node',{},'signature',{}, ...
            'refPath',{},'refId',{},'refKind',{},'isOffline',{})
        SelectedLibraryRow double = NaN
        Context struct = struct()
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj, pipeObj)
            if nargin >= 2 && ~isempty(shallowObj)
                app.Context.shallow = shallowObj;
                app.Context.shallowObj = shallowObj;
            end
            if nargin >= 3 && ~isempty(pipeObj)
                if isa(pipeObj, 'pipeline')
                    app.Context.pipeObj = pipeObj;
                end
                [nodes, edges] = unpackPipeline(app, pipeObj);
                app.Data.nodes = nodes;
                app.Data.edges = edges;
            end

            app.ModuleIdCounter = numel(app.Data.nodes);

            initAxes(app);
            initTables(app);
            redrawAll(app);
            createDynamicSaveButton(app);
        end

        % Button pushed function: AddmoduleButton
        function AddmoduleButtonPushed(app, event)
            app.PendingAddModule = true;
            app.PendingTemplate = getTemplateByDisplay(app, app.ModuletypeDropDown.Value);
        end

        % Selection changed function: UIModuleLibraryTable
        function UIModuleLibraryTableSelectionChanged(app, event)
            sel = app.UIModuleLibraryTable.Selection;
            if isempty(sel)
                app.SelectedLibraryRow = NaN;
                return;
            end
            app.SelectedLibraryRow = sel(1,1);
        end

        % Button pushed function: DuplicatefromlibraryButton
        function DuplicatefromlibraryButtonPushed(app, event)
            duplicateSelectedLibraryModule(app);
        end

        % Button pushed function: RefreshlibraryButton
        function RefreshlibraryButtonPushed(app, event)
            updateModuleLibraryTable(app);
        end

        function LinkselectednodefromlibraryMenuSelected(app, event)
            linkSelectedNodeFromLibrary(app);
        end

        function SaveselectednodetolibraryMenuSelected(app, event)
            saveSelectedNodeToOfflineLibrary(app);
        end

        function UnlinkselectednodeMenuSelected(app, event)
            unlinkSelectedNodeReference(app);
        end

        % Button pushed function: ConnectDisconnectmodulesButton
        function ConnectDisconnectmodulesButtonPushed(app, event)
            if numel(app.SelectedPorts) == 2
                connectDisconnectFromSelectedPorts(app);
                return;
            end

            if numel(app.SelectedModules) ~= 2
                uialert(app.UIFigure, 'Select 2 modules, or click 2 ports, before connecting.', 'Info');
                return;
            end

            a = app.SelectedModules(1);
            b = app.SelectedModules(2);
            if a == b
                return;
            end

            fromId = char(string(app.Data.nodes(a).id));
            toId = char(string(app.Data.nodes(b).id));
            pairEdges = [findEdgesBetween(app, fromId, toId), findEdgesBetween(app, toId, fromId)];
            pairEdges = unique(pairEdges, 'stable');

            if ~isempty(pairEdges)
                action = 'Connect new port pair';
                opts = {'Connect new port pair','Disconnect existing connection','Connect new port pair'};
                res = myDialog({'Action'}, {opts}, 'CallingApp', app.UIFigure, 'Title', 'Connection action');
                if isempty(res)
                    return;
                end
                action = char(string(res.Action{end}));

                if strcmp(action, 'Disconnect existing connection')
                    [ok, edgeIdx] = askDisconnectEdge(app, pairEdges);
                    if ok
                        app.Data.edges(edgeIdx) = [];
                        redrawEdges(app);
                        refreshStatus(app);
                    end
                    return;
                end
            end

            [ok, fromIdx, toIdx, fromPort, toPort, reason] = askConnectPorts(app, a, b);
            if ~ok
                if ~isempty(reason)
                    uialert(app.UIFigure, reason, 'Cannot connect', 'Icon','warning');
                end
                return;
            end

            fromId = char(string(app.Data.nodes(fromIdx).id));
            toId = char(string(app.Data.nodes(toIdx).id));
            e = struct('from',fromId,'to',toId,'fromPort',fromPort,'toPort',toPort,'condition','');
            if isempty(app.Data.edges)
                app.Data.edges = e;
            else
                app.Data.edges(end+1) = e;
            end

            autoHarmonizeConnection(app, fromId, toId, fromPort, toPort);

            redrawEdges(app);
            refreshStatus(app);
        end

        % Button down function: UIModulesAxes
        function UIModulesAxesButtonDown(app, event)
            if app.PendingAddModule
                pt = app.UIModulesAxes.CurrentPoint;
                pos = [pt(1,1) pt(1,2)];
                addModuleAt(app, pos);
                app.PendingAddModule = false;
                return;
            end

            clearSelection(app);
        end

        % Selection changed function: UIModuleListTable
        function UIModuleListTableSelectionChanged(app, event)
            sel = app.UIModuleListTable.Selection;
            if isempty(sel)
                return;
            end
            row = sel(1,1);
            if row > numel(app.Data.nodes)
                return;
            end

            clearPortSelection(app);
            setSelection(app, row, false);
            refreshPackageColumnForRow(app, row);
            updateParamsTable(app, row);
        end

        % Cell edit callback: UIModuleListTable
        function UIModuleListTableCellEdit(app, event)
            idx = event.Indices;
            if isempty(idx)
                return;
            end
            row = idx(1);
            col = idx(2);

            if row > numel(app.Data.nodes)
                return;
            end

            node = app.Data.nodes(row);
            val = event.NewData;

            switch col
                case 1
                    node.enabled = logical(val);
                case 2
                    node.name = char(string(val));
                case 3
                    pkgVal = char(string(val));
                    if strcmp(pkgVal, '<none>') || strcmpi(pkgVal, 'builtin')
                        pkgVal = '';
                    end
                    node.pkg = pkgVal;
                    applyPackageToNode(app, row, node.pkg);
                    node = app.Data.nodes(row);
            end

            app.Data.nodes(row) = node;
            redrawModule(app, row);
            updateModuleListTable(app);
            refreshStatus(app);
        end

        % Cell edit callback: UIModuleParametersTable
        function UIModuleParametersTableCellEdit(app, event)
            idx = event.Indices;
            if isempty(idx)
                return;
            end
            row = idx(1);
            col = idx(2);
            if col ~= 2
                return;
            end
            if isempty(app.SelectedModules)
                return;
            end

            modIdx = app.SelectedModules(1);
            if modIdx > numel(app.Data.nodes)
                return;
            end

            data = app.UIModuleParametersTable.Data;
            if isempty(data) || row > size(data,1)
                return;
            end

            key = char(string(data{row,1}));
            if isReadOnlyParamKey(app, key)
                updateParamsTable(app, modIdx);
                return;
            end
            rawVal = event.NewData;

            node = app.Data.nodes(modIdx);
            if ~isfield(node,'params') || isempty(node.params)
                node.params = struct();
            end

            oldVal = '';
            if isfield(node.params, key)
                oldVal = node.params.(key);
            end
            node.params.(key) = parseParamValueFromTable(app, rawVal, oldVal);
            app.Data.nodes(modIdx) = node;

            updateParamsTable(app, modIdx);
            refreshStatus(app);
        end

        % Selection changed function: UIModuleParametersTable
        function UIModuleParametersTableSelectionChanged(app, event)
            selection = app.UIModuleParametersTable.Selection;
            
        end

        % Button pushed function: RunpipelineButton
        function RunpipelineButtonPushed(app, event)
            pipe = buildPipelineStruct(app, false);
            ctx = app.Context;
            if ~isfield(ctx,'allowGUI')
                ctx.allowGUI = true;
            end
            try
                runPipeline(pipe, ctx);
            catch ME
                uialert(app.UIFigure, ME.message, 'Run failed', 'Icon','error');
            end
            refreshStatus(app);
        end

        % Button pushed function: SavepipelineButton
        function SavepipelineButtonPushed(app, event)
            try
                savePipelineFromGUI(app);
            catch ME
                uialert(app.UIFigure, ME.message, 'Save failed', 'Icon','error');
            end
        end

        % Button pushed function: CheckpipelineButton
        function CheckpipelineButtonPushed(app, event)
            refreshStatus(app, true);
        end

        % Button pushed function: OpenselectedmoduleButton
        function OpenselectedmoduleButtonPushed(app, event)
            if isempty(app.SelectedModules)
                return;
            end
            openModule(app, app.SelectedModules(1));
        end

        % Button pushed function: CreaterunButton
        function CreaterunButtonPushed(app, event)
            pipe = buildPipelineStruct(app, true);
            shallowObj = [];
            if isfield(app.Context,'shallow') && ~isempty(app.Context.shallow)
                shallowObj = app.Context.shallow;
            elseif isfield(app.Context,'shallowObj') && ~isempty(app.Context.shallowObj)
                shallowObj = app.Context.shallowObj;
            end

            try
                if isempty(shallowObj)
                    feval('pipelineRunGUI', pipe);
                else
                    feval('pipelineRunGUI', pipe, shallowObj);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Run GUI error', 'Icon','error');
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Window motion for dragging
        function UIFigureWindowButtonMotion(app, event)
            if isnan(app.DraggingModule)
                return;
            end
            idx = app.DraggingModule;
            if idx > numel(app.Data.nodes)
                return;
            end

            pt = app.UIModulesAxes.CurrentPoint;
            x = pt(1,1) - app.DragOffset(1);
            y = pt(1,2) - app.DragOffset(2);

            node = app.Data.nodes(idx);
            node.layout(1) = x;
            node.layout(2) = y;
            app.Data.nodes(idx) = node;

            redrawModule(app, idx);
            redrawEdges(app);
        end

        % Window up to stop dragging
        function UIFigureWindowButtonUp(app, event)
            app.DraggingModule = NaN;
        end
    end

    % Internal helpers
    methods (Access = private)

        function initAxes(app)
            cla(app.UIModulesAxes);
            app.UIModulesAxes.XLim = [0 100];
            app.UIModulesAxes.YLim = [0 100];
            app.UIModulesAxes.YDir = 'reverse';
            axis(app.UIModulesAxes, 'manual');
            hold(app.UIModulesAxes, 'on');
        end

        function initTables(app)
            app.UIModuleListTable.ColumnEditable = [true true true false false false];
            app.UIModuleListTable.ColumnFormat = { ...
                'logical', ...
                'char', ...
                getModulePackageList(app), ...
                'char', ...
                'char', ...
                'char' ...
            };
            app.ModuletypeDropDown.Items = getModuleTypeDisplay(app);

            app.UIModuleParametersTable.ColumnEditable = [false true];
            app.UIModuleLibraryTable.ColumnEditable = [false false false false false];
            updateModuleListTable(app);
            updateModuleLibraryTable(app);
            refreshPackageColumnForRow(app, 1);
        end

        function list = getModuleTypeDisplay(app)
            reg = getModuleRegistry(app);
            list = {reg.display};
        end

        function list = getModulePackageList(app)
            proc = getProcessorPackageList(app);
            clas = getClassifierPackageList(app);
            list = unique([proc(:)', clas(:)'], 'stable');
            list = list(~cellfun(@isempty, list));
            if isempty(list)
                list = {'<none>'};
            else
                list = [{'<none>'}, list];
            end
        end
        function names = getProcessorPackageList(app)
            names = listPlusPackages(app, 'processor');
        end
        function names = getClassifierPackageList(app)
            names = listPlusPackages(app, 'classification');
        end

        function names = listPlusPackages(app, kind) %#ok<INUSD>
            names = {};
            try
                here = fileparts(mfilename('fullpath'));
                repoRoot = fileparts(fileparts(here));
                root = fullfile(repoRoot, 'engine', kind);
                if ~isfolder(root)
                    return;
                end

                d = dir(fullfile(root, '+*'));
                d = d([d.isdir]);
                names = cell(1, numel(d));
                for i = 1:numel(d)
                    names{i} = erase(d(i).name, '+');
                end
                names = unique(names, 'stable');
            catch
                names = {};
            end
        end

        function row = getClasslistRowByName(app, name)
            row = [];
            try
                clFile = which('classlist.mat');
                if isempty(clFile)
                    classFile = which('classi.m');
                    if ~isempty(classFile)
                        repoRoot = fileparts(fileparts(fileparts(fileparts(classFile))));
                        clFile = fullfile(repoRoot, 'engine', 'classification', 'classlist.mat');
                    end
                end
                if ~exist(clFile,'file')
                    return;
                end
                S = load(clFile, 'classlist');
                classlist = S.classlist;
                if istable(classlist)
                    idx = find(strcmp(classlist.Name, name), 1);
                    if isempty(idx)
                        return;
                    end
                    row = classlist(idx,:);
                else
                    idx = find(strcmp(classlist(:,2), name), 1);
                    if isempty(idx)
                        return;
                    end
                    row = classlist(idx,:);
                end
            catch
                row = [];
            end
        end

        function fun = getClassifyFunFromRow(app, row)
            fun = '';
            try
                if istable(row)
                    if width(row) >= 6
                        fun = row{1,6};
                    end
                else
                    fun = row{1,6};
                end
                if iscell(fun)
                    fun = fun{1};
                end
                if isstring(fun)
                    fun = char(fun);
                end
            catch
                fun = '';
            end
        end

        function reg = getModuleRegistry(app)
            reg = struct('display',{},'type',{},'func',{},'gui',{}, ...
                'paramRequired',{},'inputs',{},'outputs',{},'defaultParams',{},'color',{});

            reg(1) = struct( ...
                'display','Dataloader', ...
                'type','dataLoader', ...
                'func','dataLoader.process', ...
                'gui','dataLoader.ui', ...
                'paramRequired',{{'path'}}, ...
                'inputs',{{}}, ...
                'outputs',{{'images'}}, ...
                'defaultParams',safeSetParam(app, 'dataLoader.setparam'), ...
                'color',[0.18 0.52 0.94]);

            reg(2) = struct( ...
                'display','ROI pattern', ...
                'type','roiPattern', ...
                'func','roiPattern.process', ...
                'gui','roiPattern.ui', ...
                'paramRequired',{{}}, ...
                'inputs',{{'images'}}, ...
                'outputs',{{'roiList'}}, ...
                'defaultParams',safeSetParam(app, 'roiPattern.setparam'), ...
                'color',[0.98 0.60 0.20]);

            reg(3) = struct( ...
                'display','ROI pattern (legacy)', ...
                'type','roiIdentify', ...
                'func','roiIdentify.process', ...
                'gui','roiIdentify.ui', ...
                'paramRequired',{{}}, ...
                'inputs',{{'images'}}, ...
                'outputs',{{'roiList'}}, ...
                'defaultParams',safeSetParam(app, 'roiIdentify.setparam'), ...
                'color',[0.98 0.60 0.20]);

            reg(4) = struct( ...
                'display','ROI manual', ...
                'type','roiManual', ...
                'func','roiManual.process', ...
                'gui','roiManual.ui', ...
                'paramRequired',{{}}, ...
                'inputs',{{'images'}}, ...
                'outputs',{{'roiList'}}, ...
                'defaultParams',safeSetParam(app, 'roiManual.setparam'), ...
                'color',[0.82 0.74 0.28]);

            reg(5) = struct( ...
                'display','ROI grid', ...
                'type','roiGrid', ...
                'func','roiGrid.process', ...
                'gui','roiGrid.ui', ...
                'paramRequired',{{}}, ...
                'inputs',{{'images'}}, ...
                'outputs',{{'roiList'}}, ...
                'defaultParams',safeSetParam(app, 'roiGrid.setparam'), ...
                'color',[0.15 0.72 0.72]);

            reg(6) = struct( ...
                'display','ROI tracked', ...
                'type','roiTracked', ...
                'func','roiTracked.process', ...
                'gui','roiTracked.ui', ...
                'paramRequired',{{}}, ...
                'inputs',{{'roiList','masks'}}, ...
                'outputs',{{'roiList'}}, ...
                'defaultParams',safeSetParam(app, 'roiTracked.setparam'), ...
                'color',[0.76 0.44 0.88]);

            reg(7) = struct( ...
                'display','ROI extraction', ...
                'type','roiExtract', ...
                'func','roiExtract.process', ...
                'gui','roiExtract.ui', ...
                'paramRequired',{{}}, ...
                'inputs',{{'roiList'}}, ...
                'outputs',{{'channels'}}, ...
                'defaultParams',safeSetParam(app, 'roiExtract.setparam'), ...
                'color',[0.10 0.68 0.38]);

            reg(8) = struct( ...
                'display','Processor', ...
                'type','processor', ...
                'func','', ...
                'gui','processDataGUI', ...
                'paramRequired',{{'pkg'}}, ...
                'inputs',{{'inputChannels'}}, ...
                'outputs',{{'dataSeries'}}, ...
                'defaultParams',struct('pkg',''), ...
                'color',[0.55 0.55 0.55]);

            reg(9) = struct( ...
                'display','Classifier', ...
                'type','classifier', ...
                'func','', ...
                'gui','classifierGUI', ...
                'paramRequired',{{'pkg'}}, ...
                'inputs',{{'inputChannels'}}, ...
                'outputs',{{'dataSeries'}}, ...
                'defaultParams',struct('pkg',''), ...
                'color',[0.40 0.40 0.70]);
        end

        function params = safeSetParam(app, funName)
            try
                params = feval(funName, struct());
            catch
                params = struct();
            end
        end

        function tpl = getTemplateByDisplay(app, displayName)
            reg = getModuleRegistry(app);
            idx = find(strcmpi({reg.display}, displayName), 1);
            if isempty(idx)
                tpl = reg(1);
            else
                tpl = reg(idx);
            end
        end

        function displayName = getDisplayFromType(app, typeName)
            reg = getModuleRegistry(app);
            idx = find(strcmpi({reg.type}, typeName), 1);
            if isempty(idx)
                displayName = typeName;
            else
                displayName = reg(idx).display;
            end
        end

        function addModuleAt(app, pos)
            tpl = app.PendingTemplate;
            if isempty(fieldnames(tpl))
                tpl = getTemplateByDisplay(app, app.ModuletypeDropDown.Value);
            end

            app.ModuleIdCounter = app.ModuleIdCounter + 1;
            id = sprintf('%s_%d', lower(tpl.type), app.ModuleIdCounter);
            if ~isempty(app.Data.nodes)
                existing = {app.Data.nodes.id};
                while any(strcmp(existing, id))
                    app.ModuleIdCounter = app.ModuleIdCounter + 1;
                    id = sprintf('%s_%d', lower(tpl.type), app.ModuleIdCounter);
                end
            end

            node = struct();
            node.id = id;
            node.name = id;
            node.type = tpl.type;
            node.func = tpl.func;
            node.gui = tpl.gui;
            node.guiMode = 'replace';
            node.paramRequired = tpl.paramRequired;
            node.inputs = tpl.inputs;
            node.outputs = tpl.outputs;
            node.params = tpl.defaultParams;
            node.enabled = true;
            node.status = '';
            node.pkg = '';
            node.layout = [pos(1) pos(2) 20 10];
            node.contract = makeNodeContract(app, node.type, node.pkg);
            [node.inputs, node.outputs] = ioFromContract(app, node.contract);

            if isempty(app.Data.nodes)
                app.Data.nodes = node;
            else
                app.Data.nodes(end+1) = node;
            end

            drawModule(app, numel(app.Data.nodes));
            updateModuleListTable(app);
            refreshStatus(app);
        end

        function drawModule(app, idx)
            node = app.Data.nodes(idx);
            [x,y,w,h] = deal(node.layout(1), node.layout(2), node.layout(3), node.layout(4));
            pts = [x y; x+w-4 y; x+w y+h/2; x+w-4 y+h; x y+h];

            col = getModuleColor(app, node);

            hPatch = patch(app.UIModulesAxes, pts(:,1), pts(:,2), col, ...
                'EdgeColor','k','LineWidth',0.5,'ButtonDownFcn',@app.modulePatchButtonDown);
            hPatch.UserData = idx;

            hText = text(app.UIModulesAxes, x+1, y+h/2, buildNodeCaption(app,node), ...
                'VerticalAlignment','middle', 'FontSize',12, 'Interpreter','none');
            hBadge = text(app.UIModulesAxes, x+0.8, y+h-0.7, buildNodeBadge(app,node), ...
                'HorizontalAlignment','left', ...
                'VerticalAlignment','top', ...
                'FontSize',8, ...
                'FontWeight','bold', ...
                'Interpreter','none', ...
                'Color',[0.15 0.15 0.15], ...
                'BackgroundColor',[0.98 0.95 0.72], ...
                'Margin',1);

            hMarker = plot(app.UIModulesAxes, x+w-2, y+2, 'o', 'MarkerSize',6, ...
                'MarkerEdgeColor','k', 'MarkerFaceColor',[0 0.8 0], 'Visible','off');

            if idx > numel(app.ModuleHandles)
                app.ModuleHandles(idx) = hPatch;
                app.ModuleTextHandles(idx) = hText;
                app.ModuleBadgeHandles(idx) = hBadge;
                app.ModuleMarkers(idx) = hMarker;
            else
                app.ModuleHandles(idx) = hPatch;
                app.ModuleTextHandles(idx) = hText;
                app.ModuleBadgeHandles(idx) = hBadge;
                app.ModuleMarkers(idx) = hMarker;
            end

            cm = uicontextmenu(app.UIFigure);
            uimenu(cm,'Text','Open module','MenuSelectedFcn',@(s,e)openModule(app, idx));
            uimenu(cm,'Text','Delete module','MenuSelectedFcn',@(s,e)deleteModule(app, idx));
            hPatch.ContextMenu = cm;

            drawPortsForModule(app, idx);
            redrawModule(app, idx);
            redrawEdges(app);
        end

        function redrawModule(app, idx)
            if idx > numel(app.Data.nodes) || idx > numel(app.ModuleHandles)
                return;
            end
            node = app.Data.nodes(idx);
            hPatch = app.ModuleHandles(idx);
            hText = app.ModuleTextHandles(idx);
            hBadge = app.ModuleBadgeHandles(idx);
            hMarker = app.ModuleMarkers(idx);
            if isempty(hPatch) || ~isvalid(hPatch)
                return;
            end

            [x,y,w,h] = deal(node.layout(1), node.layout(2), node.layout(3), node.layout(4));
            pts = [x y; x+w-4 y; x+w y+h/2; x+w-4 y+h; x y+h];
            hPatch.XData = pts(:,1);
            hPatch.YData = pts(:,2);
            hPatch.FaceColor = getModuleColor(app, node);

            hText.Position = [x+1 y+h/2 0];
            hText.String = buildNodeCaption(app,node);
            if ~isempty(hBadge) && isvalid(hBadge)
                badgeTxt = buildNodeBadge(app,node);
                hBadge.Position = [x+0.8 y+h-0.7 0];
                hBadge.String = badgeTxt;
                if isempty(badgeTxt)
                    hBadge.Visible = 'off';
                else
                    hBadge.Visible = 'on';
                end
            end

            if node.enabled
                hMarker.Visible = 'on';
                hMarker.XData = x+w-2;
                hMarker.YData = y+2;
                st = lower(char(string(getfielddefault(app, node, 'status', ''))));
                if isNodeConnected(app, node)
                    hMarker.MarkerFaceColor = [0 0.75 0.2];
                elseif contains(st,'disabled')
                    hMarker.MarkerFaceColor = [0.6 0.6 0.6];
                else
                    hMarker.MarkerFaceColor = [0.90 0.20 0.20];
                end
            else
                hMarker.Visible = 'on';
                hMarker.XData = x+w-2;
                hMarker.YData = y+2;
                hMarker.MarkerFaceColor = [0.6 0.6 0.6];
            end

            drawPortsForModule(app, idx);
            updateSelectionStyle(app);
        end

        function cap = buildNodeCaption(app, node)
            pkg = getfielddefault(app, node, 'pkg', '');
            if isempty(pkg)
                cap = char(string(node.name));
            else
                cap = sprintf('%s\n[%s]', char(string(node.name)), char(string(pkg)));
            end
        end

        function badge = buildNodeBadge(app, node)
            badge = '';
            c = getNodeContract(app, node);
            if isempty(c) || ~isstruct(c)
                return;
            end

            tokens = {};
            req = getfielddefault(app, c, 'requirements', struct());
            cap = getfielddefault(app, c, 'capabilities', struct());

            if isfield(req,'images') && isstruct(req.images) && logical(getfielddefault(app, req.images, 'required', false))
                n = double(getfielddefault(app, req.images, 'channelsMin', 0));
                if n > 0
                    tokens{end+1} = sprintf('IMG %dch', n); %#ok<AGROW>
                else
                    tokens{end+1} = 'IMG'; %#ok<AGROW>
                end
            end

            if isfield(req,'roi') && isstruct(req.roi) && logical(getfielddefault(app, req.roi, 'required', false))
                n = double(getfielddefault(app, req.roi, 'channelsMin', 0));
                if n > 0
                    tokens{end+1} = sprintf('ROI %dch', n); %#ok<AGROW>
                else
                    tokens{end+1} = 'ROI'; %#ok<AGROW>
                end
                if logical(getfielddefault(app, req.roi, 'masks', false))
                    tokens{end+1} = 'MASK'; %#ok<AGROW>
                end
                if logical(getfielddefault(app, req.roi, 'dataSeries', false))
                    tokens{end+1} = 'DS'; %#ok<AGROW>
                end
            end

            if logical(getfielddefault(app, cap, 'outputsMasks', false))
                tokens{end+1} = '+MASK'; %#ok<AGROW>
            end
            if logical(getfielddefault(app, cap, 'outputsDataSeries', false))
                tokens{end+1} = '+DS'; %#ok<AGROW>
            end
            if logical(getfielddefault(app, cap, 'outputsChannels', false))
                tokens{end+1} = '+CH'; %#ok<AGROW>
            end

            if ~isempty(tokens)
                badge = strjoin(tokens, ' | ');
            end
        end

        function drawPortsForModule(app, idx)
            if idx > numel(app.Data.nodes)
                return;
            end

            clearPortGraphicsForModule(app, idx);

            node = app.Data.nodes(idx);
            c = getNodeContract(app, node);

            inH = gobjects(0);
            inL = gobjects(0);
            for k = 1:numel(c.in)
                pname = c.in(k).name;
                [x,y] = edgeAnchor(app, node, false, pname);
                meta = struct('moduleIdx',idx,'isOut',false,'portName',char(string(pname)));
                h = plot(app.UIModulesAxes, x, y, 'o', ...
                    'MarkerSize',7, ...
                    'MarkerEdgeColor',[0.00 0.45 0.74], ...
                    'MarkerFaceColor',[1 1 1], ...
                    'LineWidth',1.2, ...
                    'ButtonDownFcn',@app.portButtonDown);
                h.UserData = meta;

                ht = text(app.UIModulesAxes, x-0.8, y, char(string(pname)), ...
                    'HorizontalAlignment','right', ...
                    'VerticalAlignment','middle', ...
                    'FontSize',11, ...
                    'Color',[0.00 0.45 0.74], ...
                    'Interpreter','none', ...
                    'ButtonDownFcn',@app.portButtonDown);
                ht.UserData = meta;

                cm = uicontextmenu(app.UIFigure);
                mi = uimenu(cm,'Text','Disconnect links on this input');
                mi.UserData = meta;
                mi.MenuSelectedFcn = @(s,e)disconnectEdgesForPort(app,s.UserData);
                h.ContextMenu = cm;
                ht.ContextMenu = cm;

                inH(end+1) = h; %#ok<AGROW>
                inL(end+1) = ht; %#ok<AGROW>
            end

            outH = gobjects(0);
            outL = gobjects(0);
            for k = 1:numel(c.out)
                pname = c.out(k).name;
                [x,y] = edgeAnchor(app, node, true, pname);
                meta = struct('moduleIdx',idx,'isOut',true,'portName',char(string(pname)));
                h = plot(app.UIModulesAxes, x, y, 'o', ...
                    'MarkerSize',7, ...
                    'MarkerEdgeColor',[0.47 0.67 0.19], ...
                    'MarkerFaceColor',[1 1 1], ...
                    'LineWidth',1.2, ...
                    'ButtonDownFcn',@app.portButtonDown);
                h.UserData = meta;

                ht = text(app.UIModulesAxes, x+0.8, y, char(string(pname)), ...
                    'HorizontalAlignment','left', ...
                    'VerticalAlignment','middle', ...
                    'FontSize',11, ...
                    'Color',[0.20 0.50 0.10], ...
                    'Interpreter','none', ...
                    'ButtonDownFcn',@app.portButtonDown);
                ht.UserData = meta;

                cm = uicontextmenu(app.UIFigure);
                mo = uimenu(cm,'Text','Disconnect links on this output');
                mo.UserData = meta;
                mo.MenuSelectedFcn = @(s,e)disconnectEdgesForPort(app,s.UserData);
                h.ContextMenu = cm;
                ht.ContextMenu = cm;

                outH(end+1) = h; %#ok<AGROW>
                outL(end+1) = ht; %#ok<AGROW>
            end

            app.InPortHandles{idx} = inH;
            app.OutPortHandles{idx} = outH;
            app.InPortLabelHandles{idx} = inL;
            app.OutPortLabelHandles{idx} = outL;
            updatePortSelectionStyle(app);
        end

        function clearPortGraphicsForModule(app, idx)
            if idx <= numel(app.InPortHandles) && ~isempty(app.InPortHandles{idx})
                hh = app.InPortHandles{idx};
                hh = hh(isgraphics(hh));
                if ~isempty(hh), delete(hh); end
                app.InPortHandles{idx} = gobjects(0);
            end
            if idx <= numel(app.OutPortHandles) && ~isempty(app.OutPortHandles{idx})
                hh = app.OutPortHandles{idx};
                hh = hh(isgraphics(hh));
                if ~isempty(hh), delete(hh); end
                app.OutPortHandles{idx} = gobjects(0);
            end
            if idx <= numel(app.InPortLabelHandles) && ~isempty(app.InPortLabelHandles{idx})
                hh = app.InPortLabelHandles{idx};
                hh = hh(isgraphics(hh));
                if ~isempty(hh), delete(hh); end
                app.InPortLabelHandles{idx} = gobjects(0);
            end
            if idx <= numel(app.OutPortLabelHandles) && ~isempty(app.OutPortLabelHandles{idx})
                hh = app.OutPortLabelHandles{idx};
                hh = hh(isgraphics(hh));
                if ~isempty(hh), delete(hh); end
                app.OutPortLabelHandles{idx} = gobjects(0);
            end
        end

        function portButtonDown(app, src, event)
            if ~isprop(src,'UserData') || isempty(src.UserData)
                return;
            end
            meta = src.UserData;
            if ~isstruct(meta) || ~all(isfield(meta, {'moduleIdx','isOut','portName'}))
                return;
            end

            mod = app.UIFigure.CurrentModifier;
            additive = any(strcmp(mod,'shift'));
            setPortSelection(app, meta, additive);
            setSelection(app, meta.moduleIdx, additive);

            if numel(app.SelectedPorts) == 2
                connectDisconnectFromSelectedPorts(app);
            end
        end

        function setPortSelection(app, meta, additive)
            if nargin < 3
                additive = false;
            end

            if ~additive
                app.SelectedPorts = meta;
            else
                cur = app.SelectedPorts;
                if isempty(cur)
                    app.SelectedPorts = meta;
                else
                    same = false(1,numel(cur));
                    for i = 1:numel(cur)
                        same(i) = cur(i).moduleIdx == meta.moduleIdx && ...
                            logical(cur(i).isOut) == logical(meta.isOut) && ...
                            strcmp(char(string(cur(i).portName)), char(string(meta.portName)));
                    end
                    if any(same)
                        cur(same) = [];
                        app.SelectedPorts = cur;
                    else
                        app.SelectedPorts = [cur, meta];
                    end
                end
            end

            if numel(app.SelectedPorts) > 2
                app.SelectedPorts = app.SelectedPorts(end-1:end);
            end

            updatePortSelectionStyle(app);
        end

        function clearPortSelection(app)
            app.SelectedModules = [];
            app.SelectedPorts = struct('moduleIdx',{},'isOut',{},'portName',{});
            updatePortSelectionStyle(app);
        end

        function connectDisconnectFromSelectedPorts(app)
            if numel(app.SelectedPorts) ~= 2
                return;
            end

            p1 = app.SelectedPorts(1);
            p2 = app.SelectedPorts(2);

            if logical(p1.isOut) == logical(p2.isOut)
                uialert(app.UIFigure, 'Select one output port and one input port.', 'Invalid selection', 'Icon','warning');
                return;
            end

            if p1.isOut
                fromMeta = p1;
                toMeta = p2;
            else
                fromMeta = p2;
                toMeta = p1;
            end

            if fromMeta.moduleIdx > numel(app.Data.nodes) || toMeta.moduleIdx > numel(app.Data.nodes)
                return;
            end

            fromNode = app.Data.nodes(fromMeta.moduleIdx);
            toNode = app.Data.nodes(toMeta.moduleIdx);

            [okCompat, why] = arePortsCompatible(app, fromNode, fromMeta.portName, toNode, toMeta.portName);
            if ~okCompat
                uialert(app.UIFigure, ['Incompatible ports: ' why], 'Cannot connect', 'Icon','warning');
                return;
            end

            fromId = char(string(fromNode.id));
            toId = char(string(toNode.id));
            edgeIdx = findEdgeByPortMap(app, fromId, toId, fromMeta.portName, toMeta.portName);

            if ~isempty(edgeIdx)
                app.Data.edges(edgeIdx) = [];
            else
                e = struct('from',fromId,'to',toId,'fromPort',char(string(fromMeta.portName)), ...
                    'toPort',char(string(toMeta.portName)),'condition','');
                if isempty(app.Data.edges)
                    app.Data.edges = e;
                else
                    app.Data.edges(end+1) = e;
                end
                autoHarmonizeConnection(app, fromId, toId, fromMeta.portName, toMeta.portName);
            end

            redrawEdges(app);
            refreshStatus(app);
            clearPortSelection(app);
        end

        function edgeIdx = findEdgeByPortMap(app, fromId, toId, fromPort, toPort)
            edgeIdx = [];
            if isempty(app.Data.edges)
                return;
            end

            for i = 1:numel(app.Data.edges)
                e = app.Data.edges(i);
                if strcmp(getEdgeField(app, e,'from',''), fromId) && ...
                   strcmp(getEdgeField(app, e,'to',''), toId) && ...
                   strcmp(getEdgeField(app, e,'fromPort',''), char(string(fromPort))) && ...
                   strcmp(getEdgeField(app, e,'toPort',''), char(string(toPort)))
                    edgeIdx = i;
                    return;
                end
            end
        end

        function disconnectEdgesForPort(app, meta)
            if isempty(app.Data.edges)
                return;
            end
            if meta.moduleIdx > numel(app.Data.nodes)
                return;
            end

            nodeId = char(string(app.Data.nodes(meta.moduleIdx).id));
            pname = char(string(meta.portName));
            edgeList = [];

            for i = 1:numel(app.Data.edges)
                e = app.Data.edges(i);
                if meta.isOut
                    if strcmp(getEdgeField(app, e,'from',''), nodeId) && strcmp(getEdgeField(app, e,'fromPort',''), pname)
                        edgeList(end+1) = i; %#ok<AGROW>
                    end
                else
                    if strcmp(getEdgeField(app, e,'to',''), nodeId) && strcmp(getEdgeField(app, e,'toPort',''), pname)
                        edgeList(end+1) = i; %#ok<AGROW>
                    end
                end
            end

            if isempty(edgeList)
                return;
            end

            if numel(edgeList) == 1
                edgeIdx = edgeList(1);
            else
                [ok, edgeIdx] = askDisconnectEdge(app, edgeList);
                if ~ok
                    return;
                end
            end

            app.Data.edges(edgeIdx) = [];
            redrawEdges(app);
            refreshStatus(app);
        end

        function redrawAll(app)
            cla(app.UIModulesAxes);
            hold(app.UIModulesAxes,'on');

            app.ModuleHandles = gobjects(0);
            app.ModuleTextHandles = gobjects(0);
            app.ModuleBadgeHandles = gobjects(0);
            app.ModuleMarkers = gobjects(0);
            app.EdgeHandles = gobjects(0);
            app.EdgeLabelHandles = gobjects(0);
            app.InPortHandles = {};
            app.OutPortHandles = {};
            app.InPortLabelHandles = {};
            app.OutPortLabelHandles = {};
            app.SelectedPorts = struct('moduleIdx',{},'isOut',{},'portName',{});

            for i = 1:numel(app.Data.nodes)
                drawModule(app, i);
            end
            redrawEdges(app);
            updateModuleListTable(app);
            refreshStatus(app);
        end

        function redrawEdges(app)
            if ~isempty(app.EdgeHandles)
                try
                    delete(app.EdgeHandles(ishandle(app.EdgeHandles)));
                catch
                end
            end
            if ~isempty(app.EdgeLabelHandles)
                try
                    delete(app.EdgeLabelHandles(ishandle(app.EdgeLabelHandles)));
                catch
                end
            end
            app.EdgeHandles = gobjects(0);
            app.EdgeLabelHandles = gobjects(0);

            if isempty(app.Data.edges)
                return;
            end

            for i = 1:numel(app.Data.edges)
                e = app.Data.edges(i);
                [ok1, fromIdx] = getNodeIndexById(app, getEdgeField(app, e,'from',''));
                [ok2, toIdx] = getNodeIndexById(app, getEdgeField(app, e,'to',''));
                if ~ok1 || ~ok2
                    continue;
                end

                n1 = app.Data.nodes(fromIdx);
                n2 = app.Data.nodes(toIdx);
                [x1,y1] = edgeAnchor(app, n1, true,  getEdgeField(app, e,'fromPort',''));
                [x2,y2] = edgeAnchor(app, n2, false, getEdgeField(app, e,'toPort',''));

                h = plot(app.UIModulesAxes, [x1 x2], [y1 y2], '-', 'Color',[0 0 0]);
                app.EdgeHandles(end+1) = h; %#ok<AGROW>
            end
        end

        function setSelection(app, idx, additive)
            if nargin < 3
                additive = false;
            end
            if ~additive
                app.SelectedModules = idx;
            else
                app.SelectedModules = unique([app.SelectedModules idx]);
                if numel(app.SelectedModules) > 2
                    app.SelectedModules = app.SelectedModules(end-1:end);
                end
            end
            updateSelectionStyle(app);

            if idx <= size(app.UIModuleListTable.Data,1)
                app.UIModuleListTable.Selection = [idx 2];
            end
        end

        function clearSelection(app)
            app.SelectedModules = [];
            clearPortSelection(app);
            updateSelectionStyle(app);
        end

        function updateSelectionStyle(app)
            for i = 1:numel(app.ModuleHandles)
                if isempty(app.ModuleHandles(i)) || ~isvalid(app.ModuleHandles(i))
                    continue;
                end
                if ismember(i, app.SelectedModules)
                    app.ModuleHandles(i).LineWidth = 2;
                else
                    app.ModuleHandles(i).LineWidth = 0.5;
                end
            end
            updatePortSelectionStyle(app);
        end

        function updatePortSelectionStyle(app)
            for i = 1:numel(app.InPortHandles)
                hh = app.InPortHandles{i};
                if isempty(hh), continue; end
                for k = 1:numel(hh)
                    if ~isgraphics(hh(k)), continue; end
                    hh(k).MarkerFaceColor = [1 1 1];
                    hh(k).MarkerSize = 7;
                end
            end

            for i = 1:numel(app.OutPortHandles)
                hh = app.OutPortHandles{i};
                if isempty(hh), continue; end
                for k = 1:numel(hh)
                    if ~isgraphics(hh(k)), continue; end
                    hh(k).MarkerFaceColor = [1 1 1];
                    hh(k).MarkerSize = 7;
                end
            end

            for i = 1:numel(app.SelectedPorts)
                meta = app.SelectedPorts(i);
                if meta.moduleIdx < 1
                    continue;
                end

                if meta.isOut
                    if meta.moduleIdx > numel(app.OutPortHandles)
                        continue;
                    end
                    hh = app.OutPortHandles{meta.moduleIdx};
                else
                    if meta.moduleIdx > numel(app.InPortHandles)
                        continue;
                    end
                    hh = app.InPortHandles{meta.moduleIdx};
                end

                if isempty(hh)
                    continue;
                end

                for k = 1:numel(hh)
                    if ~isgraphics(hh(k))
                        continue;
                    end
                    if ~isprop(hh(k),'UserData') || isempty(hh(k).UserData)
                        continue;
                    end
                    ud = hh(k).UserData;
                    if isstruct(ud) && strcmp(char(string(ud.portName)), char(string(meta.portName)))
                        hh(k).MarkerFaceColor = [1.0 0.85 0.15];
                        hh(k).MarkerSize = 9;
                    end
                end
            end
        end

        function tf = isBuiltinNodeType(app, nodeType) %#ok<INUSD>
            t = lower(char(string(nodeType)));
            tf = any(strcmp(t, {'dataloader','roiidentify','roipattern','roimanual','roigrid','roiextract','roitracked'}));
        end

        function updateModuleListTable(app)
            n = numel(app.Data.nodes);
            data = cell(n,6);
            for i = 1:n
                node = app.Data.nodes(i);
                data{i,1} = logical(getfielddefault(app, node,'enabled',true));
                data{i,2} = getfielddefault(app, node,'name',node.id);
                pkg = getfielddefault(app, node,'pkg','');
                if isempty(pkg)
                    if isBuiltinNodeType(app, getfielddefault(app, node,'type',''))
                        pkg = 'builtin';
                    else
                        pkg = '<none>';
                    end
                end
                data{i,3} = pkg;
                data{i,4} = strjoin(cellstr(node.inputs(:)), ', ');
                data{i,5} = strjoin(cellstr(node.outputs(:)), ', ');
                data{i,6} = getfielddefault(app, node,'status','');
            end
            app.UIModuleListTable.Data = data;
            if ~isempty(app.UIModuleLibraryTable) && isvalid(app.UIModuleLibraryTable)
                updateModuleLibraryTable(app);
            end
        end

        function updateModuleLibraryTable(app)
            entries = collectModuleLibraryEntries(app);
            app.LibraryEntries = entries;
            n = numel(entries);
            data = cell(n,5);
            for i = 1:n
                data{i,1} = entries(i).name;
                data{i,2} = entries(i).type;
                if isempty(entries(i).pkg)
                    data{i,3} = 'builtin';
                else
                    data{i,3} = entries(i).pkg;
                end
                data{i,4} = entries(i).source;
                data{i,5} = strjoin(cellstr(string(entries(i).node.outputs(:))), ', ');
            end
            app.UIModuleLibraryTable.Data = data;
            if n == 0
                app.SelectedLibraryRow = NaN;
                return;
            end
            if isnan(app.SelectedLibraryRow) || app.SelectedLibraryRow < 1 || app.SelectedLibraryRow > n
                app.SelectedLibraryRow = 1;
            end
            app.UIModuleLibraryTable.Selection = [app.SelectedLibraryRow 1];
        end

        function entries = collectModuleLibraryEntries(app)
            entries = struct('name',{},'type',{},'pkg',{},'source',{},'node',{},'signature',{}, ...
                'refPath',{},'refId',{},'refKind',{},'isOffline',{});

            for i = 1:numel(app.Data.nodes)
                entry = makeLibraryEntryFromNode(app, app.Data.nodes(i), 'current pipeline');
                entries = appendLibraryEntry(app, entries, entry);
            end

            try
                vars = evalin('base', 'whos');
            catch
                vars = struct('name',{},'class',{});
            end

            for i = 1:numel(vars)
                cls = char(string(vars(i).class));
                vname = char(string(vars(i).name));
                try
                    switch lower(cls)
                        case 'pipeline'
                            obj = evalin('base', vname);
                            for j = 1:numel(obj)
                                [nodes, ~] = unpackPipeline(app, obj(j));
                                for k = 1:numel(nodes)
                                    entry = makeLibraryEntryFromNode(app, nodes(k), ['workspace pipeline: ' vname]);
                                    entries = appendLibraryEntry(app, entries, entry);
                                end
                            end
                        case 'process'
                            obj = evalin('base', vname);
                            for j = 1:numel(obj)
                                entry = makeLibraryEntryFromProcess(app, obj(j), ['workspace processor: ' vname]);
                                entries = appendLibraryEntry(app, entries, entry);
                            end
                        case 'classi'
                            obj = evalin('base', vname);
                            for j = 1:numel(obj)
                                entry = makeLibraryEntryFromClassi(app, obj(j), ['workspace classifier: ' vname]);
                                entries = appendLibraryEntry(app, entries, entry);
                            end
                        case 'shallow'
                            obj = evalin('base', vname);
                            entries = appendProjectLibraryEntries(app, entries, obj, ['workspace project: ' vname]);
                    end
                catch
                end
            end

            shallowObj = [];
            if isfield(app.Context,'shallow') && ~isempty(app.Context.shallow)
                shallowObj = app.Context.shallow;
            elseif isfield(app.Context,'shallowObj') && ~isempty(app.Context.shallowObj)
                shallowObj = app.Context.shallowObj;
            end
            if ~isempty(shallowObj)
                entries = appendProjectLibraryEntries(app, entries, shallowObj, 'current project');
            end

            offlineEntries = loadOfflineLibraryEntries(app);
            for i = 1:numel(offlineEntries)
                entries = appendLibraryEntry(app, entries, offlineEntries(i));
            end
        end

        function entries = appendProjectLibraryEntries(app, entries, shallowObj, sourcePrefix)
            if isempty(shallowObj) || ~isa(shallowObj, 'shallow')
                return;
            end
            if numel(shallowObj) > 1
                for ii = 1:numel(shallowObj)
                    entries = appendProjectLibraryEntries(app, entries, shallowObj(ii), sprintf('%s #%d', sourcePrefix, ii));
                end
                return;
            end
            try
                procList = getfielddefault(app, shallowObj.processing, 'processor', []);
                for i = 1:numel(procList)
                    try
                        if isa(procList(i), 'process') && isvalid(procList(i))
                            entry = makeLibraryEntryFromProcess(app, procList(i), [sourcePrefix ' processors']);
                            entries = appendLibraryEntry(app, entries, entry);
                        end
                    catch
                    end
                end
            catch
            end
            try
                classList = getfielddefault(app, shallowObj.processing, 'classification', []);
                for i = 1:numel(classList)
                    try
                        if isa(classList(i), 'classi') && isvalid(classList(i))
                            entry = makeLibraryEntryFromClassi(app, classList(i), [sourcePrefix ' classifiers']);
                            entries = appendLibraryEntry(app, entries, entry);
                        end
                    catch
                    end
                end
            catch
            end
        end

        function entries = appendLibraryEntry(app, entries, entry) %#ok<INUSD>
            if isempty(entry) || ~isstruct(entry) || ~isfield(entry,'signature')
                return;
            end
            if isempty(entries)
                entries = entry;
                return;
            end
            if any(strcmp({entries.signature}, entry.signature))
                return;
            end
            entries(end+1) = entry; %#ok<AGROW>
        end

        function entries = loadOfflineLibraryEntries(app)
            entries = struct('name',{},'type',{},'pkg',{},'source',{},'node',{},'signature',{}, ...
                'refPath',{},'refId',{},'refKind',{},'isOffline',{});
            libFile = getOfflineLibraryFile(app);
            if exist(libFile, 'file') ~= 2
                return;
            end
            try
                S = load(libFile, 'entries');
                if isfield(S, 'entries') && isstruct(S.entries)
                    tmp = S.entries;
                    for i = 1:numel(tmp)
                        tmp(i) = finalizeLibraryEntry(app, tmp(i)); %#ok<AGROW>
                    end
                    entries = tmp;
                end
            catch
            end
        end

        function saveOfflineLibraryEntries(app, entries)
            libFile = getOfflineLibraryFile(app);
            libDir = fileparts(libFile);
            if exist(libDir, 'dir') ~= 7
                mkdir(libDir);
            end
            save(libFile, 'entries');
        end

        function libFile = getOfflineLibraryFile(app) %#ok<INUSD>
            root = '';
            try
                guiPath = which('pipelineGUI');
                if ~isempty(guiPath)
                    root = fileparts(fileparts(char(string(guiPath))));
                end
            catch
            end
            if isempty(root) || exist(root, 'dir') ~= 7
                root = fullfile(pwd, 'structure');
            end
            libFile = fullfile(root, 'cache', 'pipeline_module_library.mat');
        end

        function rememberLibraryEntry(app, entry)
            if isempty(entry) || ~isstruct(entry)
                return;
            end
            entry = finalizeLibraryEntry(app, entry);
            cur = loadOfflineLibraryEntries(app);
            cur = appendLibraryEntry(app, cur, entry);
            saveOfflineLibraryEntries(app, cur);
        end

        function rememberNodeInOfflineLibrary(app, node, source)
            entry = makeLibraryEntryFromNode(app, node, source);
            rememberLibraryEntry(app, entry);
        end

        function rememberPipelineNodesInOfflineLibrary(app, nodes, source)
            if isempty(nodes)
                return;
            end
            cur = loadOfflineLibraryEntries(app);
            for i = 1:numel(nodes)
                entry = makeLibraryEntryFromNode(app, nodes(i), source);
                cur = appendLibraryEntry(app, cur, entry);
            end
            saveOfflineLibraryEntries(app, cur);
        end

        function entry = finalizeLibraryEntry(app, entry)
            if isempty(entry) || ~isstruct(entry)
                return;
            end
            node = getObjectFieldDefault(app, entry, 'node', struct());
            [refPath, refId, refKind] = extractNodeReference(app, node);
            entry = ensureLibraryEntryFields(app, entry);
            entry.refPath = refPath;
            entry.refId = refId;
            entry.refKind = refKind;
            if isempty(entry.source)
                entry.source = 'offline library';
            end
            if ~isfield(entry, 'isOffline') || isempty(entry.isOffline)
                entry.isOffline = contains(lower(entry.source), 'offline');
            end
            sigPath = lower(char(string(refPath)));
            entry.signature = lower(sprintf('%s|%s|%s|%s|%s|%s', ...
                char(string(entry.source)), char(string(entry.type)), char(string(entry.pkg)), ...
                char(string(entry.name)), sigPath, char(string(refId))));
        end

        function entry = ensureLibraryEntryFields(app, entry) %#ok<INUSD>
            defaults = struct('name','','type','','pkg','','source','','node',struct(), ...
                'signature','','refPath','','refId','','refKind','','isOffline',false);
            fn = fieldnames(defaults);
            for i = 1:numel(fn)
                key = fn{i};
                if ~isfield(entry, key)
                    entry.(key) = defaults.(key);
                end
            end
        end

        function [refPath, refId, refKind] = extractNodeReference(app, node) %#ok<INUSD>
            refPath = '';
            refId = '';
            refKind = '';
            if ~isstruct(node) || ~isfield(node, 'params') || ~isstruct(node.params)
                return;
            end
            p = node.params;
            if isfield(p, 'modulePath') && ~isempty(p.modulePath)
                refPath = char(string(p.modulePath));
            end
            if isfield(p, 'moduleId') && ~isempty(p.moduleId)
                refId = char(string(p.moduleId));
            end
            if isfield(p, 'moduleKind') && ~isempty(p.moduleKind)
                refKind = char(string(p.moduleKind));
            elseif any(strcmpi(char(string(getfielddefault(app, node, 'type', ''))), {'processor','classifier'}))
                refKind = char(string(getfielddefault(app, node, 'type', '')));
            end
        end

        function procObj = loadLinkedProcessReference(app, node)
            procObj = [];
            [refPath, refId, refKind] = extractNodeReference(app, node);
            if ~strcmpi(refKind, 'processor') || isempty(refPath) || isempty(refId)
                return;
            end
            snap = fullfile(refPath, [refId '_processor.mat']);
            if exist(snap, 'file') ~= 2
                return;
            end
            try
                [procObj, ~] = processLoad(snap);
            catch
                procObj = [];
            end
        end

        function classObj = loadLinkedClassifierReference(app, node)
            classObj = [];
            [refPath, refId, refKind] = extractNodeReference(app, node);
            if ~strcmpi(refKind, 'classifier') || isempty(refPath) || isempty(refId)
                return;
            end
            snap = fullfile(refPath, [refId '_classification.mat']);
            if exist(snap, 'file') ~= 2
                return;
            end
            try
                [classObj, ~] = classiLoad(snap);
            catch
                classObj = [];
            end
        end

        function tmpProc = applyProcessReferenceForGui(app, tmpProc, refProc) %#ok<INUSD>
            props = {'path','strid','description','category','processFun','processArg','runProfiles'};
            for i = 1:numel(props)
                name = props{i};
                try
                    if isprop(refProc, name)
                        val = refProc.(name);
                        if ~isempty(val)
                            tmpProc.(name) = val;
                        end
                    end
                catch
                end
            end
        end

        function tmpClassi = applyClassifierReferenceForGui(app, tmpClassi, refClassi) %#ok<INUSD>
            props = {'path','strid','description','category','channel','channelName','channelName2', ...
                'classes','classifyFun','trainingFun','classifierPkg','outputType','outputFun','outputArg','trainingParam','runProfiles'};
            for i = 1:numel(props)
                name = props{i};
                try
                    if isprop(refClassi, name)
                        val = refClassi.(name);
                        if ~isempty(val)
                            tmpClassi.(name) = val;
                        end
                    end
                catch
                end
            end
        end

        function entry = makeLibraryEntryFromNode(app, node, source)
            node = normalizeLibraryNode(app, node);
            entry = struct();
            entry.name = char(string(getfielddefault(app, node, 'name', getfielddefault(app, node, 'id', 'module'))));
            entry.type = char(string(getfielddefault(app, node, 'type', 'module')));
            entry.pkg = char(string(getfielddefault(app, node, 'pkg', '')));
            entry.source = char(string(source));
            entry.node = node;
            entry.signature = '';
            entry.refPath = '';
            entry.refId = '';
            entry.refKind = '';
            entry.isOffline = false;
            entry = finalizeLibraryEntry(app, entry);
        end

        function entry = makeLibraryEntryFromProcess(app, procObj, source)
            node = struct();
            node.type = 'processor';
            node.name = char(string(getObjectFieldDefault(app, procObj, 'strid', 'processor')));
            node.id = node.name;
            node.func = char(string(getObjectFieldDefault(app, procObj, 'processFun', '')));
            node.gui = 'processDataGUI';
            node.guiMode = 'replace';
            node.paramRequired = {'pkg'};
            pkgName = inferPkgFromFunction(app, node.func, 'process');
            node.pkg = pkgName;
            params = struct();
            arg = getObjectFieldDefault(app, procObj, 'processArg', struct());
            if isstruct(arg)
                params = arg;
            end
            if ~isempty(pkgName)
                params.pkg = pkgName;
            end
            refPath = char(string(getObjectFieldDefault(app, procObj, 'path', '')));
            refId = char(string(getObjectFieldDefault(app, procObj, 'strid', '')));
            if ~isempty(refPath)
                params.modulePath = refPath;
            end
            if ~isempty(refId)
                params.moduleId = refId;
            end
            params.moduleKind = 'processor';
            node.params = params;
            node.enabled = true;
            node.status = '';
            node.layout = [0 0 20 10];
            entry = makeLibraryEntryFromNode(app, node, source);
        end

        function entry = makeLibraryEntryFromClassi(app, classObj, source)
            node = struct();
            node.type = 'classifier';
            node.name = char(string(getObjectFieldDefault(app, classObj, 'strid', 'classifier')));
            node.id = node.name;
            node.func = char(string(getObjectFieldDefault(app, classObj, 'classifyFun', '')));
            node.gui = 'classifierGUI';
            node.guiMode = 'replace';
            node.paramRequired = {'pkg'};
            pkgName = char(string(getObjectFieldDefault(app, classObj, 'classifierPkg', '')));
            if isempty(pkgName)
                pkgName = inferPkgFromFunction(app, node.func, 'classify');
            end
            node.pkg = pkgName;
            params = struct();
            if ~isempty(pkgName)
                params.pkg = pkgName;
            end
            ch1 = char(string(getObjectFieldDefault(app, classObj, 'channelName', '')));
            ch2 = char(string(getObjectFieldDefault(app, classObj, 'channelName2', '')));
            if ~isempty(ch1) && ~isempty(ch2)
                params.channels = {ch1, ch2};
            elseif ~isempty(ch1)
                params.channel = ch1;
            end
            cls = getObjectFieldDefault(app, classObj, 'classes', {});
            if ~isempty(cls)
                params.classes = cls;
            end
            outType = char(string(getObjectFieldDefault(app, classObj, 'outputType', '')));
            if ~isempty(outType)
                params.outputType = outType;
            end
            refPath = char(string(getObjectFieldDefault(app, classObj, 'path', '')));
            refId = char(string(getObjectFieldDefault(app, classObj, 'strid', '')));
            if ~isempty(refPath)
                params.modulePath = refPath;
            end
            if ~isempty(refId)
                params.moduleId = refId;
            end
            params.moduleKind = 'classifier';
            node.params = params;
            node.enabled = true;
            node.status = '';
            node.layout = [0 0 20 10];
            entry = makeLibraryEntryFromNode(app, node, source);
        end

        function node = normalizeLibraryNode(app, node)
            if ~isfield(node,'id') || isempty(node.id)
                node.id = char(string(getfielddefault(app, node, 'name', getfielddefault(app, node, 'type', 'module'))));
            end
            if ~isfield(node,'name') || isempty(node.name)
                node.name = char(string(node.id));
            end
            if ~isfield(node,'enabled')
                node.enabled = true;
            end
            if ~isfield(node,'pkg')
                node.pkg = '';
            end
            if ~isfield(node,'params') || isempty(node.params) || ~isstruct(node.params)
                node.params = struct();
            end
            if ~isfield(node,'layout') || isempty(node.layout) || numel(node.layout) < 4
                node.layout = [0 0 20 10];
            end
            if ~isfield(node,'guiMode') || isempty(node.guiMode)
                node.guiMode = 'replace';
            end
            if ~isfield(node,'status')
                node.status = '';
            end
            if ~isfield(node,'func')
                node.func = '';
            end
            if ~isfield(node,'gui')
                node.gui = '';
            end
            if ~isfield(node,'paramRequired')
                node.paramRequired = {};
            end
            node = populateNodeParamsFromPackage(app, node, false);
            node.contract = getNodeContract(app, node);
            [node.inputs, node.outputs] = ioFromContract(app, node.contract);
        end

        function duplicateSelectedLibraryModule(app)
            row = app.SelectedLibraryRow;
            if isnan(row) || row < 1 || row > numel(app.LibraryEntries)
                uialert(app.UIFigure, 'Select a module from the library first.', 'No module selected', 'Icon','info');
                return;
            end

            entry = app.LibraryEntries(row);
            node = normalizeLibraryNode(app, entry.node);
            node.layout = getNextLibraryDropLayout(app);
            node.id = nextModuleId(app, node.type);
            node.name = nextModuleName(app, char(string(getfielddefault(app, node, 'name', node.id))));
            node.status = '';
            node.enabled = true;

            if isempty(app.Data.nodes)
                app.Data.nodes = node;
            else
                app.Data.nodes(end+1) = node;
            end

            idx = numel(app.Data.nodes);
            drawModule(app, idx);
            clearPortSelection(app);
            setSelection(app, idx, false);
            updateParamsTable(app, idx);
            updateModuleListTable(app);
            rememberLibraryEntry(app, entry);
            refreshStatus(app);
        end

        function linkSelectedNodeFromLibrary(app)
            if isempty(app.SelectedModules)
                uialert(app.UIFigure, 'Select a node in the pipeline first.', 'No node selected', 'Icon', 'info');
                return;
            end
            row = app.SelectedLibraryRow;
            if isnan(row) || row < 1 || row > numel(app.LibraryEntries)
                uialert(app.UIFigure, 'Select a module in the library first.', 'No library module selected', 'Icon', 'info');
                return;
            end

            idx = app.SelectedModules(1);
            if idx < 1 || idx > numel(app.Data.nodes)
                return;
            end

            entry = app.LibraryEntries(row);
            cur = app.Data.nodes(idx);
            refNode = normalizeLibraryNode(app, entry.node);
            if ~strcmpi(char(string(cur.type)), char(string(refNode.type)))
                uialert(app.UIFigure, ...
                    sprintf('Selected node is %s but library module is %s. Link only modules of the same type.', ...
                    char(string(cur.type)), char(string(refNode.type))), ...
                    'Type mismatch', 'Icon', 'warning');
                return;
            end

            keep = struct( ...
                'id', getfielddefault(app, cur, 'id', ''), ...
                'name', getfielddefault(app, cur, 'name', ''), ...
                'layout', getfielddefault(app, cur, 'layout', [0 0 20 10]), ...
                'enabled', getfielddefault(app, cur, 'enabled', true), ...
                'status', '');
            refNode.id = keep.id;
            refNode.name = keep.name;
            refNode.layout = keep.layout;
            refNode.enabled = keep.enabled;
            refNode.status = keep.status;
            if ~isfield(refNode, 'params') || ~isstruct(refNode.params)
                refNode.params = struct();
            end
            refNode.params.linkSource = entry.source;

            app.Data.nodes(idx) = refNode;
            redrawModule(app, idx);
            updateModuleListTable(app);
            updateParamsTable(app, idx);
            rememberLibraryEntry(app, entry);
            refreshStatus(app);
        end

        function saveSelectedNodeToOfflineLibrary(app)
            if isempty(app.SelectedModules)
                uialert(app.UIFigure, 'Select a node in the pipeline first.', 'No node selected', 'Icon', 'info');
                return;
            end
            idx = app.SelectedModules(1);
            if idx < 1 || idx > numel(app.Data.nodes)
                return;
            end
            rememberNodeInOfflineLibrary(app, app.Data.nodes(idx), 'offline library');
            updateModuleLibraryTable(app);
        end

        function unlinkSelectedNodeReference(app)
            if isempty(app.SelectedModules)
                uialert(app.UIFigure, 'Select a node in the pipeline first.', 'No node selected', 'Icon', 'info');
                return;
            end
            idx = app.SelectedModules(1);
            node = app.Data.nodes(idx);
            if ~isfield(node, 'params') || ~isstruct(node.params)
                return;
            end
            drop = {'modulePath','moduleId','moduleVar','moduleKind','linkSource'};
            for i = 1:numel(drop)
                if isfield(node.params, drop{i})
                    node.params = rmfield(node.params, drop{i});
                end
            end
            app.Data.nodes(idx) = node;
            updateModuleListTable(app);
            updateParamsTable(app, idx);
            refreshStatus(app);
        end

        function layout = getNextLibraryDropLayout(app)
            w = 20;
            h = 10;
            if isempty(app.Data.nodes)
                layout = [32 12 w h];
                return;
            end

            xVals = zeros(1, numel(app.Data.nodes));
            yVals = zeros(1, numel(app.Data.nodes));
            for ii = 1:numel(app.Data.nodes)
                lay = getfielddefault(app, app.Data.nodes(ii), 'layout', [32 12 w h]);
                xVals(ii) = double(lay(1));
                yVals(ii) = double(lay(2));
            end
            x = max(28, min(75, max(xVals) - 6));
            y = max(6, min(84, max(yVals) + 6));
            layout = [x y w h];
        end

        function id = nextModuleId(app, typeName)
            base = lower(char(string(typeName)));
            app.ModuleIdCounter = max(app.ModuleIdCounter, numel(app.Data.nodes));
            while true
                app.ModuleIdCounter = app.ModuleIdCounter + 1;
                id = sprintf('%s_%d', base, app.ModuleIdCounter);
                if isempty(app.Data.nodes) || ~any(strcmp({app.Data.nodes.id}, id))
                    return;
                end
            end
        end

        function nameOut = nextModuleName(app, baseName)
            nameOut = char(string(baseName));
            if isempty(app.Data.nodes)
                return;
            end
            existing = cellstr(string({app.Data.nodes.name}));
            if ~any(strcmp(existing, nameOut))
                return;
            end
            root = nameOut;
            k = 2;
            while any(strcmp(existing, sprintf('%s_copy%d', root, k)))
                k = k + 1;
            end
            nameOut = sprintf('%s_copy%d', root, k);
        end

        function pkgName = inferPkgFromFunction(app, funName, suffix) %#ok<INUSD>
            pkgName = '';
            funName = char(string(funName));
            suffix = char(string(suffix));
            if isempty(funName)
                return;
            end
            token = regexp(funName, ['^([A-Za-z]\\w*)\\.' suffix '$'], 'tokens', 'once');
            if isempty(token)
                return;
            end
            pkgName = token{1};
        end

        function val = getObjectFieldDefault(app, obj, name, default) %#ok<INUSD>
            try
                if isstruct(obj) && isfield(obj, name)
                    val = obj.(name);
                    return;
                end
                if isobject(obj) && isprop(obj, name)
                    val = obj.(name);
                    return;
                end
            catch
            end
            val = default;
        end

        function refreshPackageColumnForRow(app, row)
            list = {'<none>'};
            if nargin >= 2 && row >= 1 && row <= numel(app.Data.nodes)
                t = lower(char(string(app.Data.nodes(row).type)));
                switch t
                    case 'processor'
                        list = getProcessorPackageList(app);
                        list = list(~cellfun(@isempty, list));
                        list = unique(list, 'stable');
                        list = [{'<none>'} list(:)'];
                    case 'classifier'
                        list = getClassifierPackageList(app);
                        list = list(~cellfun(@isempty, list));
                        list = unique(list, 'stable');
                        list = [{'<none>'} list(:)'];
                    otherwise
                        if isBuiltinNodeType(app, t)
                            list = {'builtin'};
                        else
                            list = {'<none>'};
                        end
                end
            end

            fmt = app.UIModuleListTable.ColumnFormat;
            fmt{3} = list;
            app.UIModuleListTable.ColumnFormat = fmt;
        end

        function updateParamsTable(app, idx)
            if idx > numel(app.Data.nodes)
                app.UIModuleParametersTable.Data = {};
                return;
            end
            node = app.Data.nodes(idx);
            data = {};
            if isfield(node,'params') && ~isempty(node.params)
                fn = fieldnames(node.params);
                data = cell(numel(fn),2);
                for i = 1:numel(fn)
                    data{i,1} = fn{i};
                    data{i,2} = toUITableCellValue(app, paramValueToTableCell(app, node.params.(fn{i})));
                end
            end
            data = [data; buildLinkedModuleRows(app, node); buildContractTableRows(app, node)];
            app.UIModuleParametersTable.Data = data;
        end

        function tf = isReadOnlyParamKey(app, key) %#ok<INUSD>
            key = char(string(key));
            tf = startsWith(key, '[contract]') || startsWith(key, '[link]');
        end

        function rows = buildLinkedModuleRows(app, node)
            rows = cell(0,2);
            [refPath, refId, refKind] = extractNodeReference(app, node);
            if isempty(refPath) && isempty(refId)
                return;
            end
            rows(end+1,:) = {'[link] mode', 'Referenced source folder with local pipeline overrides'}; %#ok<AGROW>
            if ~isempty(refKind)
                rows(end+1,:) = {'[link] kind', refKind}; %#ok<AGROW>
            end
            if ~isempty(refId)
                rows(end+1,:) = {'[link] source id', refId}; %#ok<AGROW>
            end
            if ~isempty(refPath)
                rows(end+1,:) = {'[link] source folder', refPath}; %#ok<AGROW>
            end
            if isfield(node, 'params') && isstruct(node.params) && isfield(node.params, 'linkSource') && ~isempty(node.params.linkSource)
                rows(end+1,:) = {'[link] source entry', char(string(node.params.linkSource))}; %#ok<AGROW>
            end
        end

        function rows = buildContractTableRows(app, node)
            rows = cell(0,2);
            c = getNodeContract(app, node);
            if isempty(c) || ~isstruct(c)
                return;
            end

            rows(end+1,:) = {'[contract] summary', summarizeContract(app, c)}; %#ok<AGROW>
            rows(end+1,:) = {'[contract] support', formatContractSupport(app, c)}; %#ok<AGROW>

            reqText = formatContractRequirements(app, c);
            if ~isempty(reqText)
                rows(end+1,:) = {'[contract] requires', reqText}; %#ok<AGROW>
            end

            selText = formatContractSelectors(app, c);
            if ~isempty(selText)
                rows(end+1,:) = {'[contract] selectors', selText}; %#ok<AGROW>
            end

            capText = formatContractCapabilities(app, c);
            if ~isempty(capText)
                rows(end+1,:) = {'[contract] produces', capText}; %#ok<AGROW>
            end
        end

        function txt = summarizeContract(app, c) %#ok<INUSD>
            txt = char(string(getfielddefault(app, c, 'summary', '')));
            if isempty(strtrim(txt))
                txt = 'No semantic summary.';
            end
        end

        function txt = formatContractSupport(app, c) %#ok<INUSD>
            parts = {};
            if isfield(c,'in') && ~isempty(c.in)
                parts{end+1} = ['in: ' strjoin({c.in.name}, ', ')]; %#ok<AGROW>
            end
            if isfield(c,'out') && ~isempty(c.out)
                parts{end+1} = ['out: ' strjoin({c.out.name}, ', ')]; %#ok<AGROW>
            end
            txt = strjoin(parts, ' | ');
        end

        function txt = formatContractRequirements(app, c) %#ok<INUSD>
            txt = '';
            if ~isfield(c,'requirements') || ~isstruct(c.requirements)
                return;
            end
            req = c.requirements;
            parts = {};

            if isfield(req,'images') && isstruct(req.images) && logical(getfielddefault(app, req.images, 'required', false))
                n = double(getfielddefault(app, req.images, 'channelsMin', 0));
                if n > 0
                    parts{end+1} = sprintf('images with >=%d channel(s)', n); %#ok<AGROW>
                else
                    parts{end+1} = 'images'; %#ok<AGROW>
                end
            end

            if isfield(req,'roi') && isstruct(req.roi) && logical(getfielddefault(app, req.roi, 'required', false))
                sub = {'roiList'};
                n = double(getfielddefault(app, req.roi, 'channelsMin', 0));
                if n > 0
                    sub{end+1} = sprintf('>= %d ROI channel(s)', n); %#ok<AGROW>
                end
                if logical(getfielddefault(app, req.roi, 'masks', false))
                    sub{end+1} = 'masks'; %#ok<AGROW>
                end
                if logical(getfielddefault(app, req.roi, 'dataSeries', false))
                    sub{end+1} = 'dataSeries'; %#ok<AGROW>
                end
                parts{end+1} = strjoin(sub, ', '); %#ok<AGROW>
            end

            if isfield(req,'params') && isstruct(req.params)
                pReq = getfielddefault(app, req.params, 'required', {});
                if ~isempty(pReq)
                    parts{end+1} = ['params: ' strjoin(cellstr(string(pReq)), ', ')]; %#ok<AGROW>
                end
            end

            txt = strjoin(parts, ' | ');
        end

        function txt = formatContractSelectors(app, c) %#ok<INUSD>
            txt = '';
            if ~isfield(c,'selectors') || ~isstruct(c.selectors)
                return;
            end
            s = c.selectors;
            parts = {};

            if ~isempty(getfielddefault(app, s, 'channelParam', ''))
                parts{end+1} = ['channel=' char(string(s.channelParam))]; %#ok<AGROW>
            end
            if ~isempty(getfielddefault(app, s, 'channelsParam', ''))
                parts{end+1} = ['channels=' char(string(s.channelsParam))]; %#ok<AGROW>
            end
            if ~isempty(getfielddefault(app, s, 'framesParam', ''))
                parts{end+1} = ['frames=' char(string(s.framesParam))]; %#ok<AGROW>
            end
            if ~isempty(getfielddefault(app, s, 'outputNameParam', ''))
                parts{end+1} = ['output=' char(string(s.outputNameParam))]; %#ok<AGROW>
            end
            if isfield(s,'defaultChannels') && ~isempty(s.defaultChannels)
                parts{end+1} = ['default channels: ' strjoin(cellstr(string(s.defaultChannels)), ', ')]; %#ok<AGROW>
            end
            txt = strjoin(parts, ' | ');
        end

        function txt = formatContractCapabilities(app, c) %#ok<INUSD>
            txt = '';
            if ~isfield(c,'capabilities') || ~isstruct(c.capabilities)
                return;
            end
            cap = c.capabilities;
            parts = {};

            if logical(getfielddefault(app, cap, 'createsRoiList', false))
                parts{end+1} = 'create ROI'; %#ok<AGROW>
            end
            if logical(getfielddefault(app, cap, 'preservesRoiList', false))
                parts{end+1} = 'preserve ROI'; %#ok<AGROW>
            end
            if logical(getfielddefault(app, cap, 'outputsChannels', false))
                parts{end+1} = 'channels'; %#ok<AGROW>
            end
            if logical(getfielddefault(app, cap, 'outputsMasks', false)) || logical(getfielddefault(app, cap, 'roiMasks', false))
                parts{end+1} = 'masks'; %#ok<AGROW>
            end
            if logical(getfielddefault(app, cap, 'outputsDataSeries', false)) || logical(getfielddefault(app, cap, 'roiDataSeries', false))
                parts{end+1} = 'dataSeries'; %#ok<AGROW>
            end
            if logical(getfielddefault(app, cap, 'outputsFovList', false))
                parts{end+1} = 'fovList'; %#ok<AGROW>
            end

            txt = strjoin(parts, ', ');
        end

        function out = paramValueToTableCell(app, v) %#ok<INUSD>
            if ischar(v)
                out = v;
            elseif isstring(v)
                out = char(v);
            elseif islogical(v)
                if isscalar(v)
                    out = char(string(v));
                else
                    out = mat2str(v);
                end
            elseif isnumeric(v)
                if isempty(v)
                    out = '';
                elseif isscalar(v)
                    out = num2str(v);
                else
                    out = mat2str(v);
                end
            elseif iscell(v) || isstruct(v)
                try
                    out = jsonencode(v);
                catch
                    try
                        out = evalc('disp(v)');
                        out = strtrim(out);
                    catch
                        out = class(v);
                    end
                end
            else
                try
                    out = char(string(v));
                catch
                    out = class(v);
                end
            end
        end

        function out = toUITableCellValue(app, v) %#ok<INUSD>
            if ischar(v) || isnumeric(v) || islogical(v)
                out = v;
                return;
            end
            if isstring(v)
                out = char(v);
                return;
            end
            try
                out = char(string(v));
            catch
                try
                    out = jsonencode(v);
                catch
                    out = class(v);
                end
            end
        end

        function tf = isNodeConnected(app, node)
            tf = true;
            c = getNodeContract(app, node);
            if ~isfield(c,'in') || isempty(c.in)
                return;
            end

            nodeId = char(string(node.id));
            for k = 1:numel(c.in)
                pin = c.in(k);
                if ~isfield(pin,'required') || ~logical(pin.required)
                    continue;
                end
                srcMode = 'edge';
                if isfield(pin,'source') && ~isempty(pin.source)
                    srcMode = lower(char(string(pin.source)));
                end
                if strcmp(srcMode,'ctx')
                    continue;
                end
                pname = char(string(pin.name));
                ok = false;
                for e = 1:numel(app.Data.edges)
                    ed = app.Data.edges(e);
                    if ~strcmp(getEdgeField(app, ed, 'to', ''), nodeId)
                        continue;
                    end
                    if strcmp(getEdgeField(app, ed, 'toPort', ''), pname)
                        ok = true;
                        break;
                    end
                end
                if ~ok
                    tf = false;
                    return;
                end
            end
        end

        function out = parseParamValueFromTable(app, raw, oldVal) %#ok<INUSD>
            if isstring(raw)
                raw = char(raw);
            end

            if islogical(oldVal)
                s = lower(strtrim(char(string(raw))));
                out = any(strcmp(s, {'1','true','yes','on'}));
                return;
            end

            if isnumeric(oldVal)
                s = strtrim(char(string(raw)));
                if isempty(s)
                    out = oldVal;
                    return;
                end
                if isscalar(oldVal)
                    z = str2double(s);
                    if isnan(z)
                        out = oldVal;
                    else
                        out = z;
                    end
                else
                    z = str2num(s); %#ok<ST2NM>
                    if isempty(z)
                        out = oldVal;
                    else
                        out = z;
                    end
                end
                return;
            end

            if isstruct(oldVal) || iscell(oldVal)
                s = char(string(raw));
                try
                    out = jsondecode(s);
                catch
                    out = oldVal;
                end
                return;
            end

            out = char(string(raw));
        end
        function refreshStatus(app, showAlert)
            if nargin < 2
                showAlert = false;
            end

            pipe = buildPipelineStruct(app, true);
            ctx = app.Context;
            try
                [ok, report] = runPipelineDry(pipe, ctx, struct('allowGui', false));
            catch
                ok = false;
                report = struct('errors',{{'Validation error'}},'missingParams',[]);
            end

            [okPorts, portReport] = validatePortContracts(app, pipe, ctx);

            missingMap = containers.Map();
            deferredMap = containers.Map();
            if isfield(report,'missingParams') && ~isempty(report.missingParams)
                for i = 1:numel(report.missingParams)
                    entry = report.missingParams{i};
                    missingMap(entry.node) = entry.missing;
                end
            end
            if isfield(report,'deferredParams') && ~isempty(report.deferredParams)
                for i = 1:numel(report.deferredParams)
                    entry = report.deferredParams{i};
                    deferredMap(entry.node) = entry.missing;
                end
            end
            semanticHints = extractSemanticHints(app, report);

            nodes = app.Data.nodes;
            if isempty(nodes)
                return;
            end
            if ~isfield(nodes,'status')
                [nodes.status] = deal('');
            end
            if ~isfield(nodes,'enabled')
                [nodes.enabled] = deal(true);
            end

            for i = 1:numel(nodes)
                node = nodes(i);
                nodeId = char(string(node.id));
                if ~getfielddefault(app, node,'enabled',true)
                    node.status = 'Disabled';
                elseif isKey(portReport.nodeIssues, nodeId)
                    msgs = portReport.nodeIssues(nodeId);
                    node.status = ['Contract: ' msgs{1}];
                elseif isKey(missingMap, nodeId)
                    miss = missingMap(nodeId);
                    node.status = ['Missing: ' strjoin(miss, ', ')];
                elseif isKey(deferredMap, nodeId)
                    miss = deferredMap(nodeId);
                    node.status = ['Deferred: ' strjoin(miss, ', ')];
                elseif isKey(semanticHints, nodeId)
                    node.status = semanticHints(nodeId);
                else
                    node.status = 'OK';
                end
                node.status = decorateNodeStatus(app, node);
                nodes(i) = node;
            end

            app.Data.nodes = nodes;
            updateModuleListTable(app);
            if ~isempty(app.SelectedModules)
                try
                    updateParamsTable(app, app.SelectedModules(1));
                catch
                end
            end
            for ii = 1:numel(app.Data.nodes)
                redrawModule(app, ii);
            end

            if showAlert
                errs = {};
                warns = {};
                if isfield(report,'errors') && ~isempty(report.errors)
                    errs = [errs report.errors];
                end
                if isfield(portReport,'errors') && ~isempty(portReport.errors)
                    errs = [errs portReport.errors];
                end
                if isfield(report,'warnings') && ~isempty(report.warnings)
                    warns = [warns report.warnings];
                end

                if ~isempty(errs)
                    msg = formatValidationMessage(app, errs, warns, false);
                    uialert(app.UIFigure, msg, 'Pipeline issues', 'Icon','warning');
                elseif ~isempty(warns)
                    msg = formatValidationMessage(app, {}, warns, true);
                    uialert(app.UIFigure, msg, 'Pipeline warnings', 'Icon','warning');
                else
                    msg = sprintf('Pipeline is valid. %d node(s), %d edge(s).', numel(pipe.nodes), numel(pipe.edges));
                    uialert(app.UIFigure, msg, 'Pipeline check', 'Icon','info');
                end
            end
        end

        function out = extractSemanticHints(app, report) %#ok<INUSD>
            out = containers.Map('KeyType','char','ValueType','char');
            if ~isstruct(report) || ~isfield(report,'semantic') || ~isstruct(report.semantic)
                return;
            end
            keys = fieldnames(report.semantic);
            for i = 1:numel(keys)
                sem = report.semantic.(keys{i});
                if ~isstruct(sem)
                    continue;
                end
                label = '';
                reqCh = double(getfielddefault(app, sem, 'requiredChannels', 0));
                if reqCh > 0
                    label = sprintf('OK: needs %d ch', reqCh);
                end
                if logical(getfielddefault(app, sem, 'masksRequired', false))
                    if isempty(label)
                        label = 'OK: needs masks';
                    else
                        label = [label ', masks'];
                    end
                end
                if logical(getfielddefault(app, sem, 'dataSeriesRequired', false))
                    if isempty(label)
                        label = 'OK: needs dataSeries';
                    else
                        label = [label ', dataSeries'];
                    end
                end
                if isempty(label)
                    label = 'OK';
                end
                out(char(string(keys{i}))) = label;
            end
        end

        function msg = formatValidationMessage(app, errs, warns, noBlocking) %#ok<INUSD>
            lines = {};
            if nargin >= 4 && noBlocking
                lines{end+1} = 'No blocking error.'; %#ok<AGROW>
            end
            if ~isempty(errs)
                lines{end+1} = 'Errors:'; %#ok<AGROW>
                for i = 1:numel(errs)
                    lines{end+1} = ['- ' char(string(errs{i}))]; %#ok<AGROW>
                end
            end
            if ~isempty(warns)
                lines{end+1} = 'Warnings:'; %#ok<AGROW>
                for i = 1:numel(warns)
                    lines{end+1} = ['- ' char(string(warns{i}))]; %#ok<AGROW>
                end
            end
            msg = strjoin(lines, newline);
        end

        function idx = findEdgesBetween(app, fromId, toId)
            idx = [];
            if isempty(app.Data.edges)
                return;
            end
            for i = 1:numel(app.Data.edges)
                e = app.Data.edges(i);
                if strcmp(getEdgeField(app, e,'from',''), fromId) && strcmp(getEdgeField(app, e,'to',''), toId)
                    idx(end+1) = i; %#ok<AGROW>
                end
            end
        end

        function status = decorateNodeStatus(app, node) %#ok<INUSD>
            status = char(string(getfielddefault(app, node, 'status', '')));
            nodeType = char(string(getfielddefault(app, node, 'type', '')));
            params = getfielddefault(app, node, 'params', struct());
            if ~isstruct(params)
                return;
            end

            tag = '';
            if strcmpi(nodeType, 'roiIdentify') || strcmpi(nodeType, 'roiPattern')
                if isfield(params, 'patternList') && ~isempty(params.patternList)
                    nPat = numel(params.patternList);
                    patIdx = 1;
                    if isfield(params, 'activePatternIndex') && ~isempty(params.activePatternIndex)
                        try
                            if params.activePatternIndex >= 1 && params.activePatternIndex <= nPat
                                patIdx = params.activePatternIndex;
                            end
                        catch
                        end
                    end
                    tag = sprintf('Pattern #%d/%d', patIdx, nPat);
                end
            elseif strcmpi(nodeType, 'roiTracked')
                tags = {};
                if isfield(params, 'channel') && ~isempty(params.channel)
                    tags{end+1} = ['Channel ' char(string(params.channel))]; %#ok<AGROW>
                end
                if isfield(params, 'margin') && ~isempty(params.margin)
                    tags{end+1} = sprintf('Margin %g', double(params.margin)); %#ok<AGROW>
                end
                if isfield(params, 'fovIndex') && ~isempty(params.fovIndex)
                    try
                        tags{end+1} = sprintf('FOVs %d', numel(params.fovIndex)); %#ok<AGROW>
                    catch
                    end
                end
                tag = strjoin(tags, ', ');
            elseif strcmpi(nodeType, 'roiGrid')
                tags = {};
                modeName = 'fullframe';
                if isfield(params, 'mode') && ~isempty(params.mode)
                    modeName = lower(char(string(params.mode)));
                end
                if strcmp(modeName, 'grid')
                    gridCount = 1;
                    if isfield(params, 'gridCount') && ~isempty(params.gridCount)
                        gridCount = params.gridCount;
                    end
                    tags{end+1} = sprintf('Grid %d', round(double(gridCount))); %#ok<AGROW>
                else
                    tags{end+1} = 'Full frame'; %#ok<AGROW>
                end
                if isfield(params, 'fovIndex') && ~isempty(params.fovIndex)
                    try
                        tags{end+1} = sprintf('FOVs %d', numel(params.fovIndex)); %#ok<AGROW>
                    catch
                    end
                end
                if isfield(params, 'keepExisting') && logical(params.keepExisting)
                    tags{end+1} = 'Keep existing'; %#ok<AGROW>
                end
                tag = strjoin(tags, ', ');
            elseif strcmpi(nodeType, 'roiManual')
                tags = {};
                if isfield(params, 'fovIndex') && ~isempty(params.fovIndex)
                    try
                        tags{end+1} = sprintf('FOVs %d', numel(params.fovIndex)); %#ok<AGROW>
                    catch
                    end
                end
                if isfield(params, 'keepExisting') && logical(params.keepExisting)
                    tags{end+1} = 'Keep existing'; %#ok<AGROW>
                end
                if isfield(params, 'openFirstOnly') && logical(params.openFirstOnly)
                    tags{end+1} = 'First only'; %#ok<AGROW>
                end
                tag = strjoin(tags, ', ');
            elseif strcmpi(nodeType, 'dataLoader')
                tags = {};
                if isfield(params, 'path') && ~isempty(params.path)
                    rawPath = char(string(params.path));
                    rawPath = regexprep(rawPath, '[\/]+$', '');
                    [~, leaf] = fileparts(rawPath);
                    if isempty(leaf)
                        leaf = rawPath;
                    end
                    tags{end+1} = ['Path ' leaf]; %#ok<AGROW>
                end

                nFilters = 0;
                filterKeys = {'positionFilter','channelFilter','stackFilter'};
                for kk = 1:numel(filterKeys)
                    key = filterKeys{kk};
                    if ~isfield(params, key) || isempty(params.(key))
                        continue;
                    end
                    try
                        nFilters = nFilters + numel(params.(key));
                    catch
                        nFilters = nFilters + 1;
                    end
                end
                if nFilters > 0
                    tags{end+1} = sprintf('Filters %d', nFilters); %#ok<AGROW>
                end

                if isfield(params, 'label') && ~isempty(params.label)
                    tags{end+1} = ['Label ' char(string(params.label))]; %#ok<AGROW>
                end

                if ~isempty(tags)
                    tag = strjoin(tags, ', ');
                end
            end

            if isempty(tag)
                return;
            end
            if isempty(status)
                status = tag;
            else
                status = [status ' (' tag ')'];
            end
        end

        function tf = edgeExistsPortMap(app, fromId, toId, fromPort, toPort)
            tf = false;
            if isempty(app.Data.edges)
                return;
            end
            for i = 1:numel(app.Data.edges)
                e = app.Data.edges(i);
                if strcmp(getEdgeField(app, e,'from',''), fromId) && ...
                   strcmp(getEdgeField(app, e,'to',''), toId) && ...
                   strcmp(getEdgeField(app, e,'fromPort',''), fromPort) && ...
                   strcmp(getEdgeField(app, e,'toPort',''), toPort)
                    tf = true;
                    return;
                end
            end
        end

        function [ok, edgeIdx] = askDisconnectEdge(app, edgeIdxList)
            ok = false;
            edgeIdx = [];
            if isempty(edgeIdxList)
                return;
            end
            if numel(edgeIdxList) == 1
                ok = true;
                edgeIdx = edgeIdxList(1);
                return;
            end

            labels = cell(1, numel(edgeIdxList));
            for i = 1:numel(edgeIdxList)
                e = app.Data.edges(edgeIdxList(i));
                fromId = getEdgeField(app, e,'from','?');
                toId = getEdgeField(app, e,'to','?');
                fromPort = getEdgeField(app, e,'fromPort','?');
                toPort = getEdgeField(app, e,'toPort','?');
                labels{i} = [fromId '.' fromPort ' -> ' toId '.' toPort];
            end
            labelsDrop = [labels labels{1}];
            res = myDialog({'Connection'}, {labelsDrop}, 'CallingApp', app.UIFigure, 'Title', 'Disconnect which connection?');
            if isempty(res)
                return;
            end
            sel = char(string(res.Connection{end}));
            pick = find(strcmp(labels, sel), 1, 'first');
            if isempty(pick)
                return;
            end
            ok = true;
            edgeIdx = edgeIdxList(pick);
        end

        function [ok, fromIdxSel, toIdxSel, fromPort, toPort, reason] = askConnectPorts(app, fromIdx, toIdx)
            ok = false;
            fromIdxSel = [];
            toIdxSel = [];
            fromPort = '';
            toPort = '';
            reason = '';

            dirs = [fromIdx toIdx; toIdx fromIdx];
            labels = {};
            pairs = struct('fromIdx',{},'toIdx',{},'fromPort',{},'toPort',{});

            for d = 1:size(dirs,1)
                srcIdx = dirs(d,1);
                dstIdx = dirs(d,2);
                srcNode = app.Data.nodes(srcIdx);
                dstNode = app.Data.nodes(dstIdx);
                [outs, ins] = getNodePortNames(app, srcNode, dstNode);
                if isempty(outs) || isempty(ins)
                    continue;
                end

                for i = 1:numel(outs)
                    for j = 1:numel(ins)
                        [cok, ~] = arePortsCompatible(app, srcNode, outs{i}, dstNode, ins{j});
                        if ~cok
                            continue;
                        end
                        srcId = char(string(srcNode.id));
                        dstId = char(string(dstNode.id));
                        if edgeExistsPortMap(app, srcId, dstId, outs{i}, ins{j})
                            continue;
                        end

                        pairs(end+1) = struct( ...
                            'fromIdx',srcIdx, ...
                            'toIdx',dstIdx, ...
                            'fromPort',outs{i}, ...
                            'toPort',ins{j}); %#ok<AGROW>
                        labels{end+1} = [char(string(srcNode.name)) '.' outs{i} ' -> ' char(string(dstNode.name)) '.' ins{j}]; %#ok<AGROW>
                    end
                end
            end

            if isempty(pairs)
                reason = 'No compatible port pair between selected modules.';
                return;
            end

            if numel(pairs) == 1
                pick = 1;
            else
                pairDrop = [labels labels{1}];
                res = myDialog({'Connection'}, {pairDrop}, 'CallingApp', app.UIFigure, 'Title', 'Connect ports');
                if isempty(res)
                    return;
                end
                sel = char(string(res.Connection{end}));
                pick = find(strcmp(labels, sel), 1, 'first');
                if isempty(pick)
                    return;
                end
            end

            fromIdxSel = pairs(pick).fromIdx;
            toIdxSel = pairs(pick).toIdx;
            fromPort = pairs(pick).fromPort;
            toPort = pairs(pick).toPort;
            ok = true;
        end

        function [outs, ins] = getNodePortNames(app, fromNode, toNode) %#ok<INUSD>
            cOut = getNodeContract(app, fromNode);
            cIn = getNodeContract(app, toNode);
            outs = {cOut.out.name};
            ins = {cIn.in.name};
        end

        function [ok, why] = arePortsCompatible(app, fromNode, fromPort, toNode, toPort)
            ok = false;
            why = '';
            cOut = getNodeContract(app, fromNode);
            cIn = getNodeContract(app, toNode);
            po = cOut.out(strcmp({cOut.out.name}, fromPort));
            pi = cIn.in(strcmp({cIn.in.name}, toPort));
            if isempty(po) || isempty(pi)
                why = 'Port missing';
                return;
            end
            t1 = char(string(po(1).type));
            t2 = char(string(pi(1).type));
            ok = strcmpi(t1, t2) || strcmpi(t1, 'any') || strcmpi(t2, 'any');
            if ~ok
                why = ['Type mismatch: ' t1 ' -> ' t2];
            end
        end

        function autoHarmonizeConnection(app, fromId, toId, fromPort, toPort)
            [ok1, iFrom] = getNodeIndexById(app, fromId);
            [ok2, iTo] = getNodeIndexById(app, toId);
            if ~ok1 || ~ok2
                return;
            end

            src = app.Data.nodes(iFrom);
            dst = app.Data.nodes(iTo);
            cOut = getNodeContract(app, src);
            po = cOut.out(strcmp({cOut.out.name}, fromPort));
            if isempty(po)
                return;
            end

            portType = lower(char(string(po(1).type)));
            isNamedToken = any(strcmp(portType, {'channelset','dataseriesset','maskset'}));
            if ~isNamedToken
                return;
            end

            srcField = ['out_' matlab.lang.makeValidName(fromPort) '_name'];
            dstField = ['in_' matlab.lang.makeValidName(toPort) '_name'];

            if ~isfield(src,'params') || ~isstruct(src.params)
                src.params = struct();
            end
            if ~isfield(dst,'params') || ~isstruct(dst.params)
                dst.params = struct();
            end

            if ~isfield(src.params, srcField) || isempty(src.params.(srcField))
                src.params.(srcField) = [char(string(src.name)) '_' fromPort];
            end

            sharedName = src.params.(srcField);
            dst.params.(dstField) = sharedName;

            % Keep a conventional alias for frequent input naming.
            if strcmpi(portType, 'channelset') && strcmpi(toPort, 'inputChannels')
                dst.params.inputChannels = sharedName;
            elseif strcmpi(portType, 'dataseriesset') && strcmpi(toPort, 'dataSeries')
                dst.params.dataSeries = sharedName;
            end

            app.Data.nodes(iFrom) = src;
            app.Data.nodes(iTo) = dst;
            updateModuleListTable(app);
            if ismember(iTo, app.SelectedModules)
                updateParamsTable(app, iTo);
            end
        end


        function val = getEdgeField(app, e, fname, defaultVal)
            val = defaultVal;
            if isstruct(e) && isfield(e,fname) && ~isempty(e.(fname))
                val = char(string(e.(fname)));
            end
        end

        function [ok, idx] = getNodeIndexById(app, nodeId)
            ok = false;
            idx = [];
            if isempty(app.Data.nodes)
                return;
            end
            ids = arrayfun(@(n) char(string(n.id)), app.Data.nodes, 'UniformOutput', false);
            idx = find(strcmp(ids, nodeId), 1, 'first');
            ok = ~isempty(idx);
        end

        function c = getNodeContract(app, node)
            pkg = '';
            if isfield(node,'pkg') && ~isempty(node.pkg)
                pkg = char(string(node.pkg));
            end

            if isfield(node,'contract') && isstruct(node.contract) && isfield(node.contract,'in') && isfield(node.contract,'out')
                c = node.contract;
                hasSource = true;
                if isfield(c,'in') && ~isempty(c.in)
                    hasSource = all(arrayfun(@(p) isfield(p,'source'), c.in));
                end
                if ~hasSource
                    c = makeNodeContract(app, char(string(node.type)), pkg);
                end
                return;
            end

            c = makeNodeContract(app, char(string(node.type)), pkg);
        end

        function c = makeNodeContract(app, nodeType, pkg) %#ok<INUSD>
            c = pipelineNodeContract(struct('type', nodeType, 'pkg', pkg));
        end

        function [inputs, outputs] = ioFromContract(app, contract) %#ok<INUSD>
            inputs = {};
            outputs = {};
            if isstruct(contract) && isfield(contract,'in') && ~isempty(contract.in)
                inputs = {contract.in.name};
            end
            if isstruct(contract) && isfield(contract,'out') && ~isempty(contract.out)
                outputs = {contract.out.name};
            end
        end

        function [ok, report] = validatePortContracts(app, pipe, ctx)
            ok = true;
            report = struct('errors',{{}}, 'nodeIssues', containers.Map('KeyType','char','ValueType','any'));

            nodes = pipe.nodes;
            edges = pipe.edges;
            if isempty(nodes)
                return;
            end

            nodeIds = arrayfun(@(n) char(string(n.id)), nodes, 'UniformOutput', false);
            connectedIn = containers.Map('KeyType','char','ValueType','any');

            for i = 1:numel(edges)
                e = edges(i);
                fromId = getEdgeField(app, e,'from','');
                toId = getEdgeField(app, e,'to','');
                fromPort = getEdgeField(app, e,'fromPort','');
                toPort = getEdgeField(app, e,'toPort','');

                fromIdx = find(strcmp(nodeIds, fromId), 1, 'first');
                toIdx = find(strcmp(nodeIds, toId), 1, 'first');
                if isempty(fromIdx) || isempty(toIdx)
                    ok = false;
                    report.errors{end+1} = ['Edge references unknown node: ' fromId ' -> ' toId]; %#ok<AGROW>
                    continue;
                end

                if isempty(fromPort) || isempty(toPort)
                    ok = false;
                    report.errors{end+1} = ['Edge missing port mapping: ' fromId ' -> ' toId]; %#ok<AGROW>
                    addIssue(toId, 'missing port mapping');
                    continue;
                end

                [compat, why] = arePortsCompatible(app, nodes(fromIdx), fromPort, nodes(toIdx), toPort);
                if ~compat
                    ok = false;
                    report.errors{end+1} = ['Incompatible ports ' fromId '.' fromPort ' -> ' toId '.' toPort ' (' why ')']; %#ok<AGROW>
                    addIssue(toId, ['incompatible ' toPort]);
                    continue;
                end

                key = [toId '|' toPort];
                if isKey(connectedIn, key)
                    li = connectedIn(key);
                    li{end+1} = fromId; %#ok<AGROW>
                    connectedIn(key) = li;
                    ok = false;
                    report.errors{end+1} = ['Multiple upstream connections to ' toId '.' toPort]; %#ok<AGROW>
                    addIssue(toId, ['multiple inputs on ' toPort]);
                else
                    connectedIn(key) = {fromId};
                end
            end

            for i = 1:numel(nodes)
                n = nodes(i);
                nodeId = char(string(n.id));
                c = getNodeContract(app, n);
                for k = 1:numel(c.in)
                    if ~logical(c.in(k).required)
                        continue;
                    end

                    pname = c.in(k).name;
                    ptype = c.in(k).type;
                    psource = getfielddefault(app, c.in(k), 'source', 'edge');
                    edgeKey = [nodeId '|' pname];
                    hasEdge = isKey(connectedIn, edgeKey);
                    hasCtx = contextProvidesType(ptype, ctx);

                    mustUseEdge = strcmpi(psource, 'edge');
                    mustUseCtx = strcmpi(psource, 'context');

                    if mustUseEdge
                        isSatisfied = hasEdge;
                    elseif mustUseCtx
                        isSatisfied = hasCtx;
                    else
                        isSatisfied = hasEdge || hasCtx;
                    end

                    if ~isSatisfied
                        ok = false;
                        addIssue(nodeId, ['missing ' pname]);
                        if mustUseEdge
                            report.errors{end+1} = ['Node ' nodeId ' missing required connection for input port: ' pname]; %#ok<AGROW>
                        elseif mustUseCtx
                            report.errors{end+1} = ['Node ' nodeId ' missing required context input: ' pname]; %#ok<AGROW>
                        else
                            report.errors{end+1} = ['Node ' nodeId ' missing required input port: ' pname]; %#ok<AGROW>
                        end
                    end
                end
            end

            function addIssue(id, msg)
                if isKey(report.nodeIssues, id)
                    m = report.nodeIssues(id);
                    if ~any(strcmp(m, msg))
                        m{end+1} = msg;
                        report.nodeIssues(id) = m;
                    end
                else
                    report.nodeIssues(id) = {msg};
                end
            end

            function tf = contextProvidesType(tp, cctx)
                tf = false;
                switch lower(char(string(tp)))
                    case {'imageset','fovlist'}
                        tf = (isfield(cctx,'images') && ~isempty(cctx.images)) || ...
                             (isfield(cctx,'fovList') && ~isempty(cctx.fovList));
                        if ~tf
                            tf = isfield(cctx,'shallow') && ~isempty(cctx.shallow);
                        end
                    case 'roilist'
                        tf = (isfield(cctx,'roiList') && ~isempty(cctx.roiList)) || ...
                             (isfield(cctx,'rois') && ~isempty(cctx.rois));
                    case 'channelset'
                        tf = isfield(cctx,'channels') && ~isempty(cctx.channels);
                    case 'dataseriesset'
                        tf = (isfield(cctx,'dataSeries') && ~isempty(cctx.dataSeries)) || ...
                             (isfield(cctx,'dataseries') && ~isempty(cctx.dataseries));
                    case 'maskset'
                        tf = isfield(cctx,'masks') && ~isempty(cctx.masks);
                        if ~tf && isfield(cctx,'roiList') && ~isempty(cctx.roiList)
                            tf = localHasMaskLikeChannels(cctx.roiList);
                        end
                    otherwise
                        tf = false;
                end
            end

            function tf = localHasMaskLikeChannels(rois)
                tf = false;
                try
                    if isempty(rois)
                        return;
                    end
                    r0 = rois(1);
                    if ~isprop(r0,'display') || isempty(r0.display) || ~isfield(r0.display,'channel') || isempty(r0.display.channel)
                        return;
                    end
                    names = lower(cellstr(r0.display.channel(:)));
                    tf = any(contains(names,'mask') | contains(names,'result') | contains(names,'track'));
                catch
                    tf = false;
                end
            end
        end

        function [x,y] = edgeAnchor(app, node, isOut, portName)
            [x0,y0,w,h] = deal(node.layout(1), node.layout(2), node.layout(3), node.layout(4));
            c = getNodeContract(app, node);
            if isOut
                ports = c.out;
                x = x0 + w;
            else
                ports = c.in;
                x = x0;
            end
            y = y0 + h/2;
            if isempty(ports)
                return;
            end
            idx = find(strcmp({ports.name}, portName), 1, 'first');
            if isempty(idx)
                idx = 1;
            end
            y = y0 + (idx * h) / (numel(ports)+1);
        end

        function deleteModule(app, idx)
            if idx > numel(app.Data.nodes)
                return;
            end

            nodeId = char(string(app.Data.nodes(idx).id));
            keep = true(1,numel(app.Data.edges));
            for i = 1:numel(app.Data.edges)
                if strcmp(getEdgeField(app, app.Data.edges(i),'from',''), nodeId) || strcmp(getEdgeField(app, app.Data.edges(i),'to',''), nodeId)
                    keep(i) = false;
                end
            end
            app.Data.edges = app.Data.edges(keep);

            clearPortGraphicsForModule(app, idx);
            app.Data.nodes(idx) = [];

            if idx <= numel(app.ModuleHandles)
                delete(app.ModuleHandles(idx));
                delete(app.ModuleTextHandles(idx));
                if idx <= numel(app.ModuleBadgeHandles)
                    delete(app.ModuleBadgeHandles(idx));
                end
                delete(app.ModuleMarkers(idx));
                app.ModuleHandles(idx) = [];
                app.ModuleTextHandles(idx) = [];
                if idx <= numel(app.ModuleBadgeHandles), app.ModuleBadgeHandles(idx) = []; end
                app.ModuleMarkers(idx) = [];
            end

            if idx <= numel(app.InPortHandles), app.InPortHandles(idx) = []; end
            if idx <= numel(app.OutPortHandles), app.OutPortHandles(idx) = []; end
            if idx <= numel(app.InPortLabelHandles), app.InPortLabelHandles(idx) = []; end
            if idx <= numel(app.OutPortLabelHandles), app.OutPortLabelHandles(idx) = []; end

            app.SelectedModules = app.SelectedModules(app.SelectedModules ~= idx);
            app.SelectedModules(app.SelectedModules > idx) = app.SelectedModules(app.SelectedModules > idx) - 1;
            clearPortSelection(app);

            for i = 1:numel(app.ModuleHandles)
                if isvalid(app.ModuleHandles(i))
                    app.ModuleHandles(i).UserData = i;
                end
            end

            redrawEdges(app);
            updateModuleListTable(app);
            refreshStatus(app);
        end

        function openModule(app, idx)
            if idx > numel(app.Data.nodes)
                return;
            end
            node = app.Data.nodes(idx);
            ctx = app.Context;
            shallowObj = [];
            if isfield(ctx,'shallow') && ~isempty(ctx.shallow)
                shallowObj = ctx.shallow;
            elseif isfield(ctx,'shallowObj') && ~isempty(ctx.shallowObj)
                shallowObj = ctx.shallowObj;
            end

            try
                if strcmpi(node.type,'processor')
                    tmpProc = process(tempdir, 'pipeline_module', randi(1e9));
                    refProc = loadLinkedProcessReference(app, node);
                    if ~isempty(refProc)
                        tmpProc = applyProcessReferenceForGui(app, tmpProc, refProc);
                    end
                    pkgName = char(string(getfielddefault(app,node,'pkg','')));
                    if ~isempty(pkgName)
                        tmpProc.processFun = [pkgName '.process'];
                        try
                            p0 = feval([pkgName '.setparam'], struct());
                        catch
                            p0 = struct();
                        end
                        if isstruct(p0)
                            tmpProc.processArg = p0;
                        end
                    elseif ~isempty(getfielddefault(app,node,'func',''))
                        tmpProc.processFun = char(string(node.func));
                    end

                    if isfield(node,'params') && isstruct(node.params)
                        if isempty(tmpProc.processArg) || ~isstruct(tmpProc.processArg)
                            tmpProc.processArg = node.params;
                        else
                            fn = fieldnames(node.params);
                            for fi = 1:numel(fn)
                                tmpProc.processArg.(fn{fi}) = node.params.(fn{fi});
                            end
                        end
                    end

                    if ~isempty(shallowObj)
                        processDataGUI(shallowObj, tmpProc);
                    else
                        processDataGUI([], tmpProc);
                    end
                    return;
                end

                if strcmpi(node.type,'dataLoader')
                    ctx = struct();
                    if ~isempty(shallowObj)
                        ctx.shallow = shallowObj;
                    end
                    if isfield(node,'params') && isstruct(node.params)
                        ctx.dataLoader = node.params;
                        ctx.params = node.params;
                    end
                    ctx = dataLoader.ui(ctx);
                    if isfield(ctx,'dataLoader') && isstruct(ctx.dataLoader)
                        params = ctx.dataLoader;
                        filtered = struct();
                        keep = {'path','positionFilter','channelFilter','stackFilter','label','write','interactive'};
                        for kk = 1:numel(keep)
                            key = keep{kk};
                            if isfield(params, key)
                                filtered.(key) = params.(key);
                            end
                        end
                        node.params = filtered;
                        app.Data.nodes(idx) = node;
                        updateModuleListTable(app);
                        updateParamsTable(app, idx);
                    end
                    refreshStatus(app);
                    return;
                end

                if strcmpi(node.type,'classifier')
                    tmpClassi = classi(tempdir, 'pipeline_module', randi(1e9));
                    refClassi = loadLinkedClassifierReference(app, node);
                    if ~isempty(refClassi)
                        tmpClassi = applyClassifierReferenceForGui(app, tmpClassi, refClassi);
                    end

                    pkgName = char(string(getfielddefault(app,node,'pkg','')));
                    if ~isempty(pkgName)
                        tmpClassi.classifierPkg = pkgName;
                        if isempty(tmpClassi.classifyFun)
                            tmpClassi.classifyFun = [pkgName '.classify'];
                        end
                        if isempty(tmpClassi.trainingFun)
                            tmpClassi.trainingFun = [pkgName '.train'];
                        end
                        if strcmpi(pkgName,'cellposesam')
                            tmpClassi.category = {'Pixel'};
                        elseif strcmpi(pkgName,'cnn_lstm')
                            tmpClassi.category = {'LSTM'};
                        else
                            tmpClassi.category = {'Image'};
                        end
                    else
                        tmpClassi.category = {'Image'};
                    end

                    if ~isempty(getfielddefault(app,node,'func',''))
                        tmpClassi.classifyFun = char(string(node.func));
                    end

                    if isfield(node,'params') && isstruct(node.params)
                        if isfield(node.params,'channel') && ~isempty(node.params.channel)
                            tmpClassi.channelName = char(string(node.params.channel));
                        end
                        if isfield(node.params,'channels') && ~isempty(node.params.channels)
                            ch = node.params.channels;
                            if isstring(ch), ch = cellstr(ch); end
                            if ischar(ch), ch = {ch}; end
                            if numel(ch) >= 1 && ~isempty(ch{1})
                                tmpClassi.channelName = char(string(ch{1}));
                            end
                            if numel(ch) >= 2 && ~isempty(ch{2})
                                tmpClassi.channelName2 = char(string(ch{2}));
                            end
                        end
                        if isfield(node.params,'classes') && ~isempty(node.params.classes)
                            cls = node.params.classes;
                            if isstring(cls), cls = cellstr(cls); end
                            if ischar(cls), cls = {cls}; end
                            tmpClassi.classes = cls;
                        end
                        if isfield(node.params,'outputType') && ~isempty(node.params.outputType)
                            tmpClassi.outputType = node.params.outputType;
                        end
                    end

                    tmpClassi.category = classiNormalizeCategory(tmpClassi.category);
                    classifierGUI(tmpClassi);
                    return;
                end

                if strcmpi(node.type,'roiIdentify') || strcmpi(node.type,'roiPattern')
                    if isempty(shallowObj)
                        uialert(app.UIFigure, 'ROI pattern GUI needs a project context.', 'Info');
                        return;
                    end
                    ctx = struct();
                    ctx.shallow = shallowObj;
                    if isfield(node,'params') && isstruct(node.params)
                        ctx.roiPattern = node.params;
                        ctx.params = node.params;
                    end
                    ctx = roiPattern.ui(ctx);
                    if isfield(ctx,'roiPattern') && isstruct(ctx.roiPattern)
                        node.params = ctx.roiPattern;
                        app.Data.nodes(idx) = node;
                        updateModuleListTable(app);
                        updateParamsTable(app, idx);
                    end
                    refreshStatus(app);
                    return;
                end

                if strcmpi(node.type,'roiManual')
                    if isempty(shallowObj)
                        uialert(app.UIFigure, 'ROI manual GUI needs a project context.', 'Info');
                        return;
                    end
                    ctx = struct();
                    ctx.shallow = shallowObj;
                    if isfield(node,'params') && isstruct(node.params)
                        ctx.roiManual = node.params;
                        ctx.params = node.params;
                    end
                    ctx = roiManual.ui(ctx);
                    if isfield(ctx,'roiManual') && isstruct(ctx.roiManual)
                        node.params = ctx.roiManual;
                        app.Data.nodes(idx) = node;
                        updateModuleListTable(app);
                        updateParamsTable(app, idx);
                    end
                    refreshStatus(app);
                    return;
                end

                if strcmpi(node.type,'roiGrid')
                    if isempty(shallowObj)
                        uialert(app.UIFigure, 'ROI grid GUI needs a project context.', 'Info');
                        return;
                    end
                    ctx = struct();
                    ctx.shallow = shallowObj;
                    if isfield(node,'params') && isstruct(node.params)
                        ctx.roiGrid = node.params;
                        ctx.params = node.params;
                    end
                    ctx = roiGrid.ui(ctx);
                    if isfield(ctx,'roiGrid') && isstruct(ctx.roiGrid)
                        node.params = ctx.roiGrid;
                        app.Data.nodes(idx) = node;
                        updateModuleListTable(app);
                        updateParamsTable(app, idx);
                    end
                    refreshStatus(app);
                    return;
                end

                if strcmpi(node.type,'roiTracked')
                    if isempty(shallowObj)
                        uialert(app.UIFigure, 'Tracked ROI GUI needs a project context.', 'Info');
                        return;
                    end
                    ctx = struct();
                    ctx.shallow = shallowObj;
                    if isfield(node,'params') && isstruct(node.params)
                        ctx.roiTracked = node.params;
                        ctx.params = node.params;
                    end
                    ctx = roiTracked.ui(ctx);
                    if isfield(ctx,'roiTracked') && isstruct(ctx.roiTracked)
                        node.params = ctx.roiTracked;
                        app.Data.nodes(idx) = node;
                        updateModuleListTable(app);
                        updateParamsTable(app, idx);
                    end
                    refreshStatus(app);
                    return;
                end

                if strcmpi(node.type,'roiExtract')
                    if isempty(shallowObj)
                        uialert(app.UIFigure, 'ROI extraction GUI needs a project context.', 'Info');
                        return;
                    end
                    ctx = struct();
                    ctx.shallow = shallowObj;
                    if isfield(node,'params') && isstruct(node.params)
                        ctx.roiExtract = node.params;
                        ctx.params = node.params;
                    end
                    ctx = roiExtract.ui(ctx);
                    if isfield(ctx,'roiExtract') && isstruct(ctx.roiExtract)
                        node.params = ctx.roiExtract;
                        app.Data.nodes(idx) = node;
                        updateModuleListTable(app);
                        updateParamsTable(app, idx);
                        if isfield(ctx,'runNow') && ctx.runNow
                            try
                                runCtx = struct('shallow', shallowObj, 'roiExtract', ctx.roiExtract, 'params', ctx.roiExtract);
                                roiExtract.process(runCtx);
                            catch ME
                                uialert(app.UIFigure, ME.message, 'ROI extraction failed', 'Icon','warning');
                            end
                        end
                    end
                    refreshStatus(app);
                    return;
                end

                if ~isfield(node,'gui') || isempty(node.gui)
                    uialert(app.UIFigure, 'No GUI associated with this module.', 'Info');
                    return;
                end

                guiFn = node.gui;
                if isstring(guiFn)
                    guiFn = char(guiFn);
                end
                if ischar(guiFn)
                    if exist(guiFn,'file') == 0 && exist(guiFn,'class') == 0
                        uialert(app.UIFigure, ['GUI not available: ' guiFn], 'Info');
                        return;
                    end
                end

                if ~isempty(shallowObj)
                    feval(guiFn, shallowObj);
                else
                    feval(guiFn);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'GUI error', 'Icon','warning');
            end

            refreshStatus(app);
        end

        function applyTypeToNode(app, idx, displayName)
            tpl = getTemplateByDisplay(app, displayName);
            node = app.Data.nodes(idx);
            node.type = tpl.type;
            node.func = tpl.func;
            node.gui = tpl.gui;
            node.paramRequired = tpl.paramRequired;
            node.params = tpl.defaultParams;
            node.pkg = '';

            if strcmpi(node.type,'processor') || strcmpi(node.type,'classifier')
                node.paramRequired = {'pkg'};
                if strcmpi(node.type,'processor')
                    node.gui = 'processDataGUI';
                else
                    node.gui = 'classifierGUI';
                end
            end

            node.contract = makeNodeContract(app, node.type, node.pkg);
            [node.inputs, node.outputs] = ioFromContract(app, node.contract);
            app.Data.nodes(idx) = node;
        end

        function applyPackageToNode(app, idx, pkgName)
            if idx > numel(app.Data.nodes)
                return;
            end
            node = app.Data.nodes(idx);
            if nargin < 3
                pkgName = '';
            end
            pkgName = char(string(pkgName));

            node.pkg = pkgName;
            if ~isfield(node,'params') || isempty(node.params)
                node.params = struct();
            end
            node.params.pkg = pkgName;

            % Infer specialized node kind from package when possible.
            if ~isempty(pkgName)
                if any(strcmp(getProcessorPackageList(app), pkgName))
                    node.type = 'processor';
                elseif any(strcmp(getClassifierPackageList(app), pkgName))
                    node.type = 'classifier';
                end
            end

            if strcmpi(node.type,'processor')
                node.func = '';
                if ~isempty(pkgName)
                    node.func = [pkgName '.process'];
                    try
                        node.params = safeSetParam(app, [pkgName '.setparam']);
                    catch
                        if ~isstruct(node.params), node.params = struct(); end
                    end
                    if ~isfield(node.params,'pkg')
                        node.params.pkg = pkgName;
                    end
                end
                node.gui = 'processDataGUI';
                node.paramRequired = {'pkg'};

            elseif strcmpi(node.type,'classifier')
                node.func = '';
                if ~isempty(pkgName)
                    row = getClasslistRowByName(app, pkgName);
                    fun = getClassifyFunFromRow(app, row);
                    if ~isempty(fun)
                        node.func = fun;
                    end
                    if ~isfield(node.params,'pkg')
                        node.params.pkg = pkgName;
                    end
                end
                node.gui = 'classifierGUI';
                node.paramRequired = {'pkg'};
            end

            node.contract = makeNodeContract(app, node.type, node.pkg);
            [node.inputs, node.outputs] = ioFromContract(app, node.contract);
            app.Data.nodes(idx) = node;
        end

        function node = populateNodeParamsFromPackage(app, node, forceRefresh)
            if nargin < 3
                forceRefresh = false;
            end

            pkgName = char(string(getfielddefault(app, node, 'pkg', '')));
            if isempty(pkgName)
                return;
            end

            if ~isfield(node,'params') || ~isstruct(node.params) || isempty(node.params)
                node.params = struct();
            end

            if strcmpi(char(string(node.type)), 'processor')
                hasOnlyPkg = numel(fieldnames(node.params)) <= 1 && isfield(node.params,'pkg');
                if forceRefresh || isempty(fieldnames(node.params)) || hasOnlyPkg
                    p0 = safeSetParam(app, [pkgName '.setparam']);
                    if isstruct(p0) && ~isempty(fieldnames(p0))
                        fn = fieldnames(p0);
                        for fi = 1:numel(fn)
                            if forceRefresh || ~isfield(node.params, fn{fi}) || isempty(node.params.(fn{fi}))
                                node.params.(fn{fi}) = p0.(fn{fi});
                            end
                        end
                    end
                end
                node.params.pkg = pkgName;
                node.func = [pkgName '.process'];
                node.gui = 'processDataGUI';
                node.paramRequired = {'pkg'};

            elseif strcmpi(char(string(node.type)), 'classifier')
                node.params.pkg = pkgName;
                if ~isfield(node,'func') || isempty(node.func)
                    row = getClasslistRowByName(app, pkgName);
                    fun = getClassifyFunFromRow(app, row);
                    if ~isempty(fun)
                        node.func = fun;
                    end
                end
                node.gui = 'classifierGUI';
                node.paramRequired = {'pkg'};
            end
        end

        function col = getModuleColor(app, node)
            reg = getModuleRegistry(app);
            idx = find(strcmpi({reg.type}, node.type), 1);
            if isempty(idx)
                col = [0.7 0.7 0.7];
            else
                col = reg(idx).color;
            end
            if isfield(node,'enabled') && ~node.enabled
                col = col*0.5 + 0.5;
            end
        end
        function [nodes, edges] = unpackPipeline(app, pipeObj)
            nodes = [];
            edges = [];
            if isempty(pipeObj)
                return;
            end
            if isa(pipeObj,'pipeline')
                nodes = pipeObj.nodes;
                edges = pipeObj.edges;
            elseif isstruct(pipeObj)
                if isfield(pipeObj,'nodes'), nodes = pipeObj.nodes; end
                if isfield(pipeObj,'edges'), edges = pipeObj.edges; end
            end

            if isempty(nodes)
                return;
            end

            for i = 1:numel(nodes)
                if ~isfield(nodes(i),'layout') || isempty(nodes(i).layout)
                    nodes(i).layout = [10 10 20 10];
                end
                if ~isfield(nodes(i),'name') || isempty(nodes(i).name)
                    nodes(i).name = char(string(nodes(i).id));
                end
                if ~isfield(nodes(i),'enabled')
                    nodes(i).enabled = true;
                end
                if ~isfield(nodes(i),'pkg')
                    nodes(i).pkg = '';
                end
                nodes(i) = populateNodeParamsFromPackage(app, nodes(i), false);
                nodes(i).contract = getNodeContract(app, nodes(i));
                [nodes(i).inputs, nodes(i).outputs] = ioFromContract(app, nodes(i).contract);
            end

            % Normalize edges to id/port form
            normEdges = struct('from',{},'to',{},'fromPort',{},'toPort',{},'condition',{});
            if isempty(edges)
                edges = normEdges;
                return;
            end

            for i = 1:numel(edges)
                e = edges(i);
                fromId = '';
                toId = '';
                fromPort = '';
                toPort = '';
                cond = '';

                if isfield(e,'from') && isnumeric(e.from)
                    ii = e.from;
                    if ii >= 1 && ii <= numel(nodes)
                        fromId = char(string(nodes(ii).id));
                    end
                elseif isfield(e,'from')
                    fromId = char(string(e.from));
                end

                if isfield(e,'to') && isnumeric(e.to)
                    ii = e.to;
                    if ii >= 1 && ii <= numel(nodes)
                        toId = char(string(nodes(ii).id));
                    end
                elseif isfield(e,'to')
                    toId = char(string(e.to));
                end

                if isfield(e,'fromPort') && ~isempty(e.fromPort)
                    fromPort = char(string(e.fromPort));
                end
                if isfield(e,'toPort') && ~isempty(e.toPort)
                    toPort = char(string(e.toPort));
                end
                if isfield(e,'condition') && ~isempty(e.condition)
                    cond = char(string(e.condition));
                end

                if isempty(fromId) || isempty(toId)
                    continue;
                end

                if isempty(fromPort)
                    nodeIdsLocal = arrayfun(@(n) char(string(n.id)), nodes, 'UniformOutput', false);
                    ii = find(strcmp(nodeIdsLocal, fromId), 1, 'first');
                    if ~isempty(ii)
                        c = getNodeContract(app, nodes(ii));
                        if ~isempty(c.out), fromPort = c.out(1).name; end
                    end
                end
                if isempty(toPort)
                    nodeIdsLocal = arrayfun(@(n) char(string(n.id)), nodes, 'UniformOutput', false);
                    ii = find(strcmp(nodeIdsLocal, toId), 1, 'first');
                    if ~isempty(ii)
                        c = getNodeContract(app, nodes(ii));
                        if ~isempty(c.in), toPort = c.in(1).name; end
                    end
                end

                normEdges(end+1) = struct('from',fromId,'to',toId,'fromPort',fromPort,'toPort',toPort,'condition',cond); %#ok<AGROW>
            end

            edges = normEdges;
        end

        function pipe = buildPipelineStruct(app, includeDisabled)
            if nargin < 2
                includeDisabled = true;
            end
            pipe = struct();
            pipe.name = 'pipeline_gui';
            pipe.nodes = app.Data.nodes;
            pipe.edges = app.Data.edges;

            if ~includeDisabled
                for i = 1:numel(pipe.nodes)
                    if isfield(pipe.nodes(i),'enabled') && ~pipe.nodes(i).enabled
                        pipe.nodes(i).condition = 'false';
                    end
                end
            end
        end

        function savePipelineFromGUI(app)
            pipeObj = [];
            if isfield(app.Context,'pipeObj') && isa(app.Context.pipeObj,'pipeline')
                pipeObj = app.Context.pipeObj;
            end

            if isempty(pipeObj)
                pipeObj = pipelineNew();
                if isempty(pipeObj)
                    return;
                end
                app.Context.pipeObj = pipeObj;
            end

            pipe = buildPipelineStruct(app, true);
            pipeObj.nodes = pipe.nodes;
            pipeObj.edges = pipe.edges;
            pipelineSave(pipeObj);
            rememberPipelineNodesInOfflineLibrary(app, pipe.nodes, 'offline library');
            updateModuleLibraryTable(app);
        end

        function createDynamicSaveButton(app)
            if isfield(app.Context,'savePipelineButton')
                hb = app.Context.savePipelineButton;
                if ~isempty(hb) && isvalid(hb)
                    return;
                end
            end

            hb = uibutton(app.UIFigure, 'push');
            hb.ButtonPushedFcn = createCallbackFcn(app, @SavepipelineButtonPushed, true);
            hb.Position = [1160 822 120 24];
            hb.Text = 'Save pipeline...';
            app.Context.savePipelineButton = hb;
        end

        function modulePatchButtonDown(app, src, event)
            idx = src.UserData;
            if strcmp(app.UIFigure.SelectionType,'open')
                openModule(app, idx);
                return;
            end

            mod = app.UIFigure.CurrentModifier;
            additive = any(strcmp(mod,'shift'));
            clearPortSelection(app);
            setSelection(app, idx, additive);
            updateParamsTable(app, idx);

            pt = app.UIModulesAxes.CurrentPoint;
            node = app.Data.nodes(idx);
            app.DraggingModule = idx;
            app.DragOffset = [pt(1,1) - node.layout(1), pt(1,2) - node.layout(2)];
        end

        function val = getfielddefault(varargin)
            if nargin == 4
                s = varargin{2};
                f = varargin{3};
                default = varargin{4};
            elseif nargin == 3
                s = varargin{1};
                f = varargin{2};
                default = varargin{3};
            else
                val = [];
                return;
            end

            if isstruct(s) && isfield(s,f)
                val = s.(f);
            else
                val = default;
            end
        end

    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pipeline GUI';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.ModulelibraryLabel = uilabel(app.UIFigure);
            app.ModulelibraryLabel.Text = 'Module library';
            app.RefreshlibraryButton = uibutton(app.UIFigure, 'push');
            app.RefreshlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshlibraryButtonPushed, true);
            app.RefreshlibraryButton.Text = 'Refresh';
            app.UIModuleLibraryTable = uitable(app.UIFigure);
            app.UIModuleLibraryTable.ColumnName = {'Name'; 'Type'; 'Package'; 'Source'; 'Provides'};
            app.UIModuleLibraryTable.RowName = {};
            app.UIModuleLibraryTable.SelectionChangedFcn = createCallbackFcn(app, @UIModuleLibraryTableSelectionChanged, true);
            cmLib = uicontextmenu(app.UIFigure);
            uimenu(cmLib, 'Text', 'Duplicate into graph', ...
                'MenuSelectedFcn', createCallbackFcn(app, @DuplicatefromlibraryButtonPushed, true));
            app.UIModuleLibraryTable.ContextMenu = cmLib;
            app.DuplicatefromlibraryButton = uibutton(app.UIFigure, 'push');
            app.DuplicatefromlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicatefromlibraryButtonPushed, true);
            app.DuplicatefromlibraryButton.Text = 'Duplicate into graph';
            app.UIModulesAxes = uiaxes(app.UIFigure);
            zlabel(app.UIModulesAxes, 'Z')
            app.UIModulesAxes.XTick = [];
            app.UIModulesAxes.YTick = [];
            app.UIModulesAxes.ButtonDownFcn = createCallbackFcn(app, @UIModulesAxesButtonDown, true);
            app.ModuletypeDropDownLabel = uilabel(app.UIFigure);
            app.ModuletypeDropDownLabel.Text = 'Module type';
            app.ModuletypeDropDown = uidropdown(app.UIFigure);
            app.ModuletypeDropDown.Items = {'Dataloader', 'ROI pattern', 'ROI manual', 'ROI grid', 'ROI tracked', 'ROI extraction', 'Processor', 'Classifier'};
            app.ModuletypeDropDown.Value = 'Dataloader';
            app.AddmoduleButton = uibutton(app.UIFigure, 'push');
            app.AddmoduleButton.ButtonPushedFcn = createCallbackFcn(app, @AddmoduleButtonPushed, true);
            app.AddmoduleButton.Text = 'Add module';
            app.UIModuleListTable = uitable(app.UIFigure);
            app.UIModuleListTable.ColumnName = {'Select'; 'Name'; 'Package'; 'Requires'; 'Provides'; 'Status'};
            app.UIModuleListTable.RowName = {};
            app.UIModuleListTable.ColumnEditable = [true true true false false false];
            app.UIModuleListTable.CellEditCallback = createCallbackFcn(app, @UIModuleListTableCellEdit, true);
            app.UIModuleListTable.SelectionChangedFcn = createCallbackFcn(app, @UIModuleListTableSelectionChanged, true);
            cmNode = uicontextmenu(app.UIFigure);
            uimenu(cmNode, 'Text', 'Link selected node from selected library module', ...
                'MenuSelectedFcn', createCallbackFcn(app, @LinkselectednodefromlibraryMenuSelected, true));
            uimenu(cmNode, 'Text', 'Save selected node to offline library', ...
                'MenuSelectedFcn', createCallbackFcn(app, @SaveselectednodetolibraryMenuSelected, true));
            uimenu(cmNode, 'Text', 'Unlink selected node reference', ...
                'MenuSelectedFcn', createCallbackFcn(app, @UnlinkselectednodeMenuSelected, true));
            app.UIModuleListTable.ContextMenu = cmNode;
            app.RunpipelineButton = uibutton(app.UIFigure, 'push');
            app.RunpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @RunpipelineButtonPushed, true);
            app.RunpipelineButton.Text = 'Run pipeline';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CheckpipelineButton = uibutton(app.UIFigure, 'push');
            app.CheckpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpipelineButtonPushed, true);
            app.CheckpipelineButton.Text = 'Check pipeline';
            app.OpenselectedmoduleButton = uibutton(app.UIFigure, 'push');
            app.OpenselectedmoduleButton.ButtonPushedFcn = createCallbackFcn(app, @OpenselectedmoduleButtonPushed, true);
            app.OpenselectedmoduleButton.Text = 'Open selected module';
            app.CreaterunButton = uibutton(app.UIFigure, 'push');
            app.CreaterunButton.ButtonPushedFcn = createCallbackFcn(app, @CreaterunButtonPushed, true);
            app.CreaterunButton.Text = 'Create run...';
            app.UIModuleParametersTable = uitable(app.UIFigure);
            app.UIModuleParametersTable.ColumnName = {'Parameter'; 'Value'};
            app.UIModuleParametersTable.RowName = {};
            app.UIModuleParametersTable.CellEditCallback = createCallbackFcn(app, @UIModuleParametersTableCellEdit, true);
            app.UIModuleParametersTable.SelectionChangedFcn = createCallbackFcn(app, @UIModuleParametersTableSelectionChanged, true);
            app.ConnectDisconnectmodulesButton = uibutton(app.UIFigure, 'push');
            app.ConnectDisconnectmodulesButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDisconnectmodulesButtonPushed, true);
            app.ConnectDisconnectmodulesButton.Tooltip = {'Click 2 ports to connect/disconnect directly, or select 2 modules then press this button.'};
            app.ConnectDisconnectmodulesButton.Text = 'Connect/Disconnect modules';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pipelineGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end