classdef preferencesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UserpreferencesUIFigure  matlab.ui.Figure
        CloseButton              matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        StatusSaved              matlab.ui.control.Lamp
        SavingstatusLampLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
        
 % Description
    end
    
    methods (Access = private)

        function userprefs = normalizeBrushPreferences(app, userprefs) %#ok<INUSD>
            if ~isstruct(userprefs)
                userprefs = struct();
            end

            userprefs.painting_left_brush_radius = localRadius(userprefs, ...
                {'painting_left_brush_radius', 'painting_normal_brush_radius'}, 7);
            userprefs.painting_middle_brush_radius = localRadius(userprefs, ...
                {'painting_middle_brush_radius', 'painting_huge_brush_radius'}, 13);
            userprefs.painting_right_brush_radius = localRadius(userprefs, ...
                {'painting_right_brush_radius', 'painting_large_brush_radius'}, 4);
            userprefs.painting_eraser_brush_radius = localRadius(userprefs, ...
                {'painting_eraser_brush_radius', 'painting_fine_brush_radius'}, 7);

            if isfield(userprefs, 'painting_large_brush_size') && ...
                    ~isfield(userprefs, 'painting_left_brush_radius')
                userprefs.painting_left_brush_radius = localRadiusFromArea(userprefs.painting_large_brush_size, 7);
            end
            if isfield(userprefs, 'painting_huge_brush_size') && ...
                    ~isfield(userprefs, 'painting_middle_brush_radius')
                userprefs.painting_middle_brush_radius = localRadiusFromArea(userprefs.painting_huge_brush_size, 13);
            end
            if isfield(userprefs, 'painting_small_brush_size') && ...
                    ~isfield(userprefs, 'painting_eraser_brush_radius')
                userprefs.painting_eraser_brush_radius = localRadiusFromArea(userprefs.painting_small_brush_size, 7);
            end

            userprefs.painting_large_brush_size = userprefs.painting_left_brush_radius^2;
            userprefs.painting_small_brush_size = userprefs.painting_eraser_brush_radius^2;
            userprefs.painting_huge_brush_size = userprefs.painting_middle_brush_radius^2;

            function r = localRadius(S, names, fallback)
                r = fallback;
                for iName = 1:numel(names)
                    name = names{iName};
                    try
                        if isfield(S, name) && ~isempty(S.(name))
                            v = double(S.(name));
                            if isfinite(v) && v > 0
                                r = min(50, max(1, round(v)));
                                return;
                            end
                        end
                    catch
                    end
                end
            end

            function r = localRadiusFromArea(value, fallback)
                r = fallback;
                try
                    v = sqrt(double(value));
                    if isfinite(v) && v > 0
                        r = min(50, max(1, round(v)));
                    end
                catch
                end
            end
        end

        function [displayPrefs, tip] = displayPreferences(app, userprefs)
            userprefs = app.normalizeBrushPreferences(userprefs);
            hidden = {'tip', 'painting_large_brush_size', 'painting_small_brush_size', ...
                'painting_huge_brush_size', 'painting_normal_brush_radius', ...
                'painting_fine_brush_radius', 'painting_large_brush_radius'};

            displayPrefs = userprefs;
            for iHidden = 1:numel(hidden)
                if isfield(displayPrefs, hidden{iHidden})
                    displayPrefs = rmfield(displayPrefs, hidden{iHidden});
                end
            end

            order = {'roi_view_shortcut_keys', 'roi_view_corr_shortcut_keys', ...
                'roi_view_bounds_shortcut_keys', 'roi_view_frames_jump_size', ...
                'painting_fill_holes_shortcut', 'painting_transparency_shortcut', ...
                'painting_left_brush_radius', 'painting_middle_brush_radius', ...
                'painting_right_brush_radius', 'painting_eraser_brush_radius'};
            order = order(isfield(displayPrefs, order));
            displayPrefsOrdered = struct();
            for iOrder = 1:numel(order)
                displayPrefsOrdered.(order{iOrder}) = displayPrefs.(order{iOrder});
            end
            displayPrefs = displayPrefsOrdered;

            tipMap = containers.Map();
            tipMap('roi_view_shortcut_keys') = 'Keyboard shortcuts used to assign a class to an image; space-separated letters';
            tipMap('roi_view_corr_shortcut_keys') = 'Keyboard shortcuts used to correct GT vs prediction discrepancies';
            tipMap('roi_view_bounds_shortcut_keys') = 'Keyboard shortcuts used to set frame bounds';
            tipMap('roi_view_frames_jump_size') = 'Keyboard shortcuts used to quickly jump between frames';
            tipMap('painting_fill_holes_shortcut') = 'Keyboard shortcut used to fill holes when painting';
            tipMap('painting_transparency_shortcut') = 'Keyboard shortcuts used to change painting transparency';
            tipMap('painting_left_brush_radius') = 'Radius in pixels used with the left mouse button';
            tipMap('painting_middle_brush_radius') = 'Radius in pixels used with the middle mouse button';
            tipMap('painting_right_brush_radius') = 'Radius in pixels used with the right mouse button';
            tipMap('painting_eraser_brush_radius') = 'Radius in pixels used by shift + left mouse button eraser';

            keys = fieldnames(displayPrefs);
            tip = cell(1, numel(keys));
            for iKey = 1:numel(keys)
                if isKey(tipMap, keys{iKey})
                    tip{iKey} = tipMap(keys{iKey});
                else
                    tip{iKey} = '';
                end
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            userprefs = detecdiv_prefs_load();
            userprefs = app.normalizeBrushPreferences(userprefs);
            detecdiv_prefs_save(userprefs);

            [trainingParam, tip] = app.displayPreferences(userprefs);
            app.UserpreferencesUIFigure.UserData = [];
            si = app.UserpreferencesUIFigure.Position(4);
            struct2GUI(trainingParam, [10 si-25], 'Handle', app.UserpreferencesUIFigure, ...
                'Flag', app.StatusSaved, 'Tip', tip, 'Width', 200);
            app.Data.tip = tip;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                   delete(app.UserpreferencesUIFigure)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            uiPrefs = app.UserpreferencesUIFigure.UserData;
            userprefs = detecdiv_prefs_load();
            fields = fieldnames(uiPrefs);
            for iField = 1:numel(fields)
                userprefs.(fields{iField}) = uiPrefs.(fields{iField});
            end
            userprefs = app.normalizeBrushPreferences(userprefs);
            userprefs.tip = app.Data.tip;
            detecdiv_prefs_save(userprefs);
            app.StatusSaved.Color=[0 1 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UserpreferencesUIFigure = uifigure('Visible', 'off');
            app.UserpreferencesUIFigure.Name = 'User preferences';
            app.SavingstatusLampLabel = uilabel(app.UserpreferencesUIFigure);
            app.SavingstatusLampLabel.Text = 'Saving status:';
            app.StatusSaved = uilamp(app.UserpreferencesUIFigure);
            app.SaveButton = uibutton(app.UserpreferencesUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.UserpreferencesUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UserpreferencesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preferencesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UserpreferencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UserpreferencesUIFigure)
        end
    end
end